classdef Home < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        FinishButton        matlab.ui.control.Button
        UserTextArea        matlab.ui.control.TextArea
        UserTextAreaLabel   matlab.ui.control.Label
        AccessButton        matlab.ui.control.Button
        ModesDropDown       matlab.ui.control.DropDown
        ModesDropDownLabel  matlab.ui.control.Label
        Image2              matlab.ui.control.Image
        Image               matlab.ui.control.Image
    end


    properties (Access = private)
        IsInitialized logical = false
        IsLoggedIn logical = false

        CurrentUser char = ''
        CurrentEmail char = ''
        CurrentRole char = ''

        AdminPassword char = '112115'

        LoginTime char = ''
        SessionID char = ''

        % =============================================================
        % RAMANCUBE DATA LOCATION
        %
        % Independent from the folder containing the MATLAB app code.
        % Moving Home.mlapp or other code files must not change data paths.
        % =============================================================
        ConfiguredProjectRoot char = 'E:\PROJECTS\RamanCube'

        ProjectRoot char = ''
        InputRoot char = ''
        OutputRoot char = ''
        HistoryRoot char = ''
        HistoryFile char = ''
        SessionSummaryFile char = ''

        SelectedDataType char = ''
        SelectedFolder char = ''

        % Columns: Time | SourceApp | Action | Detail
        SessionActions cell = {}
    end


    methods (Access = public)
        function showHomeFromChild(app, sourceName)
            if nargin < 2 || isempty(sourceName)
                sourceName = 'Child app';
            end

            app.initializeHome();

            app.UIFigure.Visible = 'on';
            app.updateUserDisplay();

            app.logSessionEvent(sourceName, 'Return Home', ...
                'Returned to Home without logging out.');

            drawnow;
        end


        function sessionInfo = getSessionInfo(app)
            sessionInfo = app.buildSessionInfo();
        end


        function logSessionEvent(app, sourceApp, actionName, detailText)
            if nargin < 4
                detailText = '';
            end

            app.initializeHome();
            app.ensureHistoryFile();

            t = datestr(now, 'yyyy-mm-dd HH:MM:SS');

            sourceApp = app.cleanOneLine(sourceApp);
            actionName = app.cleanOneLine(actionName);
            detailText = app.cleanOneLine(detailText);

            if isempty(app.SessionID)
                sid = 'no_session';
            else
                sid = app.SessionID;
            end

            if isempty(app.CurrentUser)
                user = 'N/A';
            else
                user = app.CurrentUser;
            end

            if isempty(app.CurrentRole)
                role = 'N/A';
            else
                role = app.CurrentRole;
            end

            if isempty(app.SelectedDataType)
                mode = 'N/A';
            else
                mode = app.SelectedDataType;
            end

            app.SessionActions(end + 1, :) = {t, sourceApp, actionName, detailText};

            fid = fopen(app.HistoryFile, 'a');

            if fid ~= -1
                cleaner = onCleanup(@() fclose(fid));

                fprintf(fid, '%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\n', ...
                    t, sid, user, role, mode, sourceApp, actionName, detailText);
            end
        end


    end


    methods (Access = private)

        function initializeHome(app)

            if app.IsInitialized
                return
            end

            % =============================================================
            % RESOLVE DATA PROJECT ROOT
            %
            % This root is independent from the MATLAB code location.
            % =============================================================
            app.ProjectRoot = app.resolveProjectRoot();

            app.InputRoot = fullfile( ...
                app.ProjectRoot, ...
                'Data input');

            app.OutputRoot = fullfile( ...
                app.ProjectRoot, ...
                'Data output');

            app.HistoryRoot = fullfile( ...
                app.OutputRoot, ...
                '_history');

            app.HistoryFile = fullfile( ...
                app.HistoryRoot, ...
                'session_history.tsv');

            % =============================================================
            % VERIFY REQUIRED INPUT STRUCTURE
            %
            % Do not silently create a new empty input root at the code folder.
            % =============================================================
            if ~isfolder(app.InputRoot)

                error(sprintf([ ...
                    'RamanCube Data input folder was not found.\n\n' ...
                    'Expected folder:\n%s\n\n' ...
                    'Configured project root:\n%s'], ...
                    app.InputRoot, ...
                    app.ProjectRoot));
            end

            % Output folders may be created automatically.
            app.ensureFolder(app.OutputRoot);
            app.ensureFolder(app.HistoryRoot);
            app.ensureFolder(fullfile(app.HistoryRoot, 'sessions'));

            app.ensureHistoryFile();

            app.setLoggedOutUI();

            app.IsInitialized = true;
        end

        function root = resolveProjectRoot(app)

            % =============================================================
            % PRIMARY CONFIGURED DATA ROOT
            % =============================================================
            configuredRoot = strtrim( ...
                char(app.ConfiguredProjectRoot));

            if ~isempty(configuredRoot)

                expectedInput = fullfile( ...
                    configuredRoot, ...
                    'Data input');

                expectedOutput = fullfile( ...
                    configuredRoot, ...
                    'Data output');

                if isfolder(expectedInput)

                    root = configuredRoot;

                    % Output is allowed to be created if missing.
                    if ~isfolder(expectedOutput)
                        mkdir(expectedOutput);
                    end

                    return
                end
            end

            % =============================================================
            % FALLBACK MANUAL SELECTION
            %
            % Used only when the configured data root cannot be found.
            % =============================================================
            startFolder = configuredRoot;

            if isempty(startFolder) || ...
                    ~isfolder(startFolder)

                startFolder = pwd;
            end

            selectedRoot = uigetdir( ...
                startFolder, ...
                ['Select RamanCube project folder containing ' ...
                '"Data input" and "Data output"']);

            if isequal(selectedRoot, 0)

                error(sprintf([ ...
                    'RamanCube project folder could not be resolved.\n\n' ...
                    'Expected configured folder:\n%s'], ...
                    configuredRoot));
            end

            selectedInput = fullfile( ...
                selectedRoot, ...
                'Data input');

            if ~isfolder(selectedInput)

                error(sprintf([ ...
                    'The selected folder is not a valid RamanCube project.\n\n' ...
                    'Missing folder:\n%s'], ...
                    selectedInput));
            end

            selectedOutput = fullfile( ...
                selectedRoot, ...
                'Data output');

            if ~isfolder(selectedOutput)
                mkdir(selectedOutput);
            end

            root = selectedRoot;
        end


        function ensureFolder(app, folderPath)
            if ~isfolder(folderPath)
                mkdir(folderPath);
            end
        end


        function ensureHistoryFile(app)
            app.ensureFolder(app.HistoryRoot);

            if exist(app.HistoryFile, 'file') ~= 2
                fid = fopen(app.HistoryFile, 'w');
                if fid ~= -1
                    fprintf(fid, 'Time\tSessionID\tUser\tRole\tMode\tSourceApp\tAction\tDetail\n');
                    fclose(fid);
                end
            end
        end


        function setLoggedOutUI(app)
            app.UserTextArea.Value = {''};
            app.UserTextArea.Editable = 'off';
            app.UserTextArea.Enable = 'off';
            app.UserTextArea.Visible = 'on';
            app.UserTextAreaLabel.Visible = 'on';

            app.UserTextArea.FontName = 'Times New Roman';
            app.UserTextArea.FontSize = 16;

            app.FinishButton.Enable = 'off';

            app.ModesDropDown.Enable = 'on';
            app.AccessButton.Enable = 'on';
        end


        function setLoggedInUI(app)
            app.UserTextArea.Visible = 'on';
            app.UserTextAreaLabel.Visible = 'on';

            app.UserTextArea.Editable = 'off';
            app.UserTextArea.Enable = 'on';
            app.UserTextArea.FontName = 'Times New Roman';
            app.UserTextArea.FontSize = 16;

            app.updateUserDisplay();

            app.ModesDropDown.Enable = 'on';
            app.AccessButton.Enable = 'on';
            app.FinishButton.Enable = 'on';
        end


        function updateUserDisplay(app)
            app.UserTextArea.HorizontalAlignment = 'center';
            app.UserTextArea.FontName = 'Times New Roman';
            app.UserTextArea.FontSize = 16;

            if app.IsLoggedIn
                app.UserTextArea.Value = {app.CurrentUser};
            else
                app.UserTextArea.Value = {''};
            end
        end


        function ok = requireLogin(app)
            app.initializeHome();

            ok = app.IsLoggedIn;
            if ok
                return;
            end

            answer = questdlg( ...
                'Who are you?', ...
                'User authentication', ...
                'Duongle', 'Client', 'Cancel', 'Client');

            if isempty(answer) || strcmp(answer, 'Cancel')
                ok = false;
                return;
            end

            switch answer
                case 'Duongle'
                    ok = app.loginAsDuongle();

                case 'Client'
                    ok = app.loginAsClient();

                otherwise
                    ok = false;
            end

            if ok
                app.finalizeLogin();
                msgbox(sprintf('Login successful.\n\nUser: %s', app.CurrentUser), ...
                    'Login completed', 'help');
            else
                app.setLoggedOutUI();
            end
        end


        function ok = loginAsDuongle(app)
            ok = false;
            maxTry = 3;

            for k = 1:maxTry
                password = app.askPasswordMasked();

                if isempty(password)
                    return;
                end

                if strcmp(password, app.AdminPassword)
                    app.CurrentUser = 'duongle';
                    app.CurrentEmail = '';
                    app.CurrentRole = 'duongle';
                    app.IsLoggedIn = true;
                    ok = true;
                    return;
                else
                    uialert(app.UIFigure, ...
                        sprintf('Incorrect password. Attempt %d of %d.', k, maxTry), ...
                        'Login failed');
                end
            end

            app.IsLoggedIn = false;
        end


        function password = askPasswordMasked(app)
            password = '';
            confirmed = false;
            realPassword = '';

            d = uifigure( ...
                'Name', 'Duongle login', ...
                'Position', [500 500 380 165]);

            movegui(d, 'center');

            d.CloseRequestFcn = @(src, event) cancelLogin();
            d.WindowKeyPressFcn = @(src, event) keyPressed(event);

            uilabel(d, ...
                'Text', 'Enter password:', ...
                'Position', [25 100 120 22], ...
                'FontName', 'Times New Roman', ...
                'FontSize', 14);

            pwdField = uieditfield(d, 'text', ...
                'Position', [150 100 190 25], ...
                'Editable', 'off');

            uilabel(d, ...
                'Text', 'Type password, then press OK or Enter.', ...
                'Position', [25 70 320 20], ...
                'FontName', 'Times New Roman', ...
                'FontSize', 12);

            uibutton(d, 'push', ...
                'Text', 'OK', ...
                'Position', [170 30 75 30], ...
                'ButtonPushedFcn', @(src, event) confirmLogin());

            uibutton(d, 'push', ...
                'Text', 'Cancel', ...
                'Position', [260 30 75 30], ...
                'ButtonPushedFcn', @(src, event) cancelLogin());

            drawnow;
            figure(d);
            uiwait(d);

            if isvalid(d)
                if confirmed
                    password = strtrim(realPassword);
                end
                delete(d);
            end

            function keyPressed(event)
                key = event.Key;

                switch key
                    case {'return', 'enter'}
                        confirmLogin();
                        return;

                    case 'escape'
                        cancelLogin();
                        return;

                    case 'backspace'
                        if ~isempty(realPassword)
                            realPassword(end) = [];
                        end

                    otherwise
                        ch = event.Character;

                        if ~isempty(ch)
                            % Chỉ nhận ký tự in được, tránh shift/control/arrow.
                            if ischar(ch) && numel(ch) == 1 && double(ch) >= 32
                                realPassword = [realPassword ch];
                            end
                        end
                end

                pwdField.Value = repmat('*', 1, numel(realPassword));
            end

            function confirmLogin()
                confirmed = true;
                uiresume(d);
            end

            function cancelLogin()
                confirmed = false;
                uiresume(d);
            end
        end


        function ok = loginAsClient(app)
            ok = false;

            while true
                prompts = {'User name:', 'Email:'};
                dlgtitle = 'Client information';
                dims = [1 55];
                definput = {'', ''};

                answ = inputdlg(prompts, dlgtitle, dims, definput);

                if isempty(answ)
                    return;
                end

                userName = strtrim(answ{1});
                email = strtrim(answ{2});

                if isempty(userName)
                    uialert(app.UIFigure, ...
                        'User name is required.', ...
                        'Missing information');
                    continue;
                end

                if isempty(email) || isempty(regexp(email, '^[\w\.-]+@[\w\.-]+\.\w+$', 'once'))
                    uialert(app.UIFigure, ...
                        'A valid email address is required.', ...
                        'Invalid email');
                    continue;
                end

                app.CurrentUser = userName;
                app.CurrentEmail = email;
                app.CurrentRole = 'client';
                app.IsLoggedIn = true;

                ok = true;
                return;
            end
        end


        function finalizeLogin(app)
            app.LoginTime = datestr(now, 'yyyy-mm-dd HH:MM:SS');

            safeUser = app.makeSafeFileText(app.CurrentUser);

            app.SessionID = sprintf('%s_%s_%s', ...
                datestr(now, 'yyyymmdd_HHMMSS'), ...
                app.CurrentRole, ...
                safeUser);

            app.SessionActions = {};

            app.SessionSummaryFile = fullfile( ...
                app.HistoryRoot, ...
                'sessions', ...
                [app.SessionID '.txt']);

            app.setLoggedInUI();

            if strcmp(app.CurrentRole, 'client')
                detail = sprintf('email=%s; projectRoot=%s', ...
                    app.CurrentEmail, app.ProjectRoot);
            else
                detail = sprintf('email=N/A; projectRoot=%s', ...
                    app.ProjectRoot);
            end

            app.logSessionEvent('Home', 'Login', detail);
        end


        function out = makeSafeFileText(app, txt)
            if iscell(txt)
                txt = strjoin(txt, '_');
            end

            out = char(txt);
            out = strtrim(out);

            if isempty(out)
                out = 'unknown';
            end

            out = regexprep(out, '[^\w\-]', '_');
        end


        function out = cleanOneLine(app, txt)
            if nargin < 2 || isempty(txt)
                out = '';
                return;
            end

            if iscell(txt)
                txt = strjoin(txt, ' ');
            elseif isnumeric(txt)
                txt = num2str(txt);
            elseif isstruct(txt)
                try
                    txt = evalc('disp(txt)');
                catch
                    txt = 'struct';
                end
            end

            out = char(txt);
            out = regexprep(out, '[\t\r\n]+', ' ');
            out = strtrim(out);
        end


        function ok = setSelectedFolderFromMode(app)
            ok = false;
            dtype = char(app.ModesDropDown.Value);
            app.SelectedDataType = dtype;
            switch dtype
                case 'Single spectra'
                    app.SelectedFolder = fullfile( ...
                        app.InputRoot, ...
                        'single');
                case 'Raman scan'
                    app.SelectedFolder = fullfile( ...
                        app.InputRoot, ...
                        'scan');
                otherwise
                    app.SelectedDataType = '';
                    app.SelectedFolder = '';
                    return
            end
            ok = true;
        end


        function ok = validateSelectedFolder(app)
            ok = false;

            if isempty(app.SelectedDataType)
                uialert(app.UIFigure, ...
                    'Please select a mode before accessing data.', ...
                    'Mode required');
                return;
            end

            if ~isfolder(app.InputRoot)
                uialert(app.UIFigure, ...
                    sprintf('Data input folder was not found:\n\n%s', app.InputRoot), ...
                    'Input root not found');
                return;
            end

            if ~isfolder(app.SelectedFolder)
                uialert(app.UIFigure, ...
                    sprintf('Expected input folder was not found:\n\n%s', app.SelectedFolder), ...
                    'Input folder not found');
                return;
            end

            ok = true;
        end


        function sessionInfo = buildSessionInfo(app)

            sessionInfo = struct();

            % =============================================================
            % USER SESSION
            % =============================================================
            sessionInfo.SessionID = app.SessionID;
            sessionInfo.User = app.CurrentUser;
            sessionInfo.Email = app.CurrentEmail;
            sessionInfo.Role = app.CurrentRole;
            sessionInfo.LoginTime = app.LoginTime;

            % =============================================================
            % SELECTED MODE
            % =============================================================
            sessionInfo.DataType = app.SelectedDataType;
            sessionInfo.SelectedFolder = app.SelectedFolder;

            % =============================================================
            % PROJECT FOLDERS
            % =============================================================
            sessionInfo.ProjectRoot = app.ProjectRoot;
            sessionInfo.InputRoot = app.InputRoot;
            sessionInfo.OutputRoot = app.OutputRoot;


            sessionInfo.SingleInputRoot = fullfile( ...
                app.InputRoot, ...
                'single');

            sessionInfo.ScanInputRoot = fullfile( ...
                app.InputRoot, ...
                'scan');

            sessionInfo.SingleOutputRoot = fullfile( ...
                app.OutputRoot, ...
                'single');

            sessionInfo.ScanOutputRoot = fullfile( ...
                app.OutputRoot, ...
                'scan');
            
            sessionInfo.ScanWorkspaceRoot = fullfile( ...
                sessionInfo.ScanOutputRoot, ...
                'workspaces');

            % =============================================================
            % HISTORY
            % =============================================================
            sessionInfo.HistoryRoot = app.HistoryRoot;
            sessionInfo.HistoryFile = app.HistoryFile;
            sessionInfo.SessionSummaryFile = app.SessionSummaryFile;
        end


        function openNextApp(app)

            sessionInfo = app.buildSessionInfo();

            try

                switch app.SelectedDataType

                    % =========================================================
                    % SINGLE SPECTRA MODE
                    % =========================================================
                    case 'Single spectra'

                        [action, resumeFile] = ...
                            app.chooseSingleSessionAction();

                        if action == "Cancel"
                            return
                        end

                        nextApp = single_mode();

                        if ~ismethod(nextApp, 'receiveHomeSession')

                            delete(nextApp);

                            error([ ...
                                'The single_mode app does not contain ', ...
                                'the public method receiveHomeSession().']);

                        end

                        if action == "Resume"

                            nextApp.receiveHomeSession( ...
                                sessionInfo, ...
                                app, ...
                                resumeFile);

                        else

                            nextApp.receiveHomeSession( ...
                                sessionInfo, ...
                                app, ...
                                "");

                        end

                        % =========================================================
                        % RAMAN SCAN MODE
                        % =========================================================
                    case 'Raman scan'

                        nextApp = scanspectra();

                        if ~ismethod(nextApp, 'receiveHomeSession')

                            delete(nextApp);

                            error([ ...
                                'The scanspectra app does not contain ', ...
                                'the public method receiveHomeSession().']);

                        end

                        nextApp.receiveHomeSession( ...
                            sessionInfo, ...
                            app);

                    otherwise

                        uialert(app.UIFigure, ...
                            'Please select Single spectra or Raman scan.', ...
                            'Invalid mode');

                        return
                end

                % =============================================================
                % HOME AUDIT LOG
                % =============================================================
                app.logSessionEvent( ...
                    'Home', ...
                    'Open child app', ...
                    sprintf( ...
                    'target=%s; mode=%s; folder=%s', ...
                    class(nextApp), ...
                    app.SelectedDataType, ...
                    app.SelectedFolder));

                app.UIFigure.Visible = 'off';

            catch ME

                app.logSessionEvent( ...
                    'Home', ...
                    'Open child app error', ...
                    ME.message);

                uialert(app.UIFigure, ...
                    sprintf( ...
                    'Cannot open selected app.\n\n%s', ...
                    ME.message), ...
                    'Open app error');

            end
        end


        function summaryText = buildSessionSummary(app, includeAll)
            if nargin < 2
                includeAll = false;
            end

            lines = {};

            lines{end+1} = sprintf('Session ID: %s', app.SessionID);
            lines{end+1} = sprintf('User: %s', app.CurrentUser);
            lines{end+1} = sprintf('Role: %s', app.CurrentRole);

            if strcmp(app.CurrentRole, 'client')
                lines{end+1} = sprintf('Email: %s', app.CurrentEmail);
            else
                lines{end+1} = 'Email: N/A';
            end

            lines{end+1} = sprintf('Login time: %s', app.LoginTime);
            lines{end+1} = sprintf('Selected mode: %s', app.SelectedDataType);
            lines{end+1} = sprintf('Selected input folder: %s', app.SelectedFolder);
            lines{end+1} = sprintf('History file: %s', app.HistoryFile);

            n = size(app.SessionActions, 1);
            lines{end+1} = sprintf('Total recorded actions: %d', n);

            if n > 0
                lines{end+1} = 'Actions:';

                if includeAll
                    idx = 1:n;
                else
                    idx = max(1, n - 9):n;
                end

                for ii = idx
                    lines{end+1} = sprintf('- %s | %s | %s | %s', ...
                        app.SessionActions{ii, 1}, ...
                        app.SessionActions{ii, 2}, ...
                        app.SessionActions{ii, 3}, ...
                        app.SessionActions{ii, 4});
                end

                if ~includeAll && n > numel(idx)
                    lines{end+1} = sprintf('... %d older actions are saved in the history file.', ...
                        n - numel(idx));
                end
            end

            summaryText = strjoin(lines, newline);
        end


        function writeSessionSummaryFile(app)
            if isempty(app.SessionSummaryFile)
                return;
            end

            fid = fopen(app.SessionSummaryFile, 'w');

            if fid == -1
                uialert(app.UIFigure, ...
                    'The session summary file could not be written.', ...
                    'History warning');
                return;
            end

            cleaner = onCleanup(@() fclose(fid));

            fprintf(fid, '%s\n', app.buildSessionSummary(true));
        end


        function closeNow = askFinishConfirmation(app, summaryText)
            closeNow = false;

            % Temporarily hide Home so Finish window is not covered by the app.
            oldHomeVisible = app.UIFigure.Visible;
            app.UIFigure.Visible = 'off';
            drawnow;

            d = uifigure( ...
                'Name', 'Finish session', ...
                'Position', [500 250 700 560], ...
                'WindowStyle', 'normal');

            movegui(d, 'center');
            figure(d);

            d.CloseRequestFcn = @(src, event) cancelFinish();

            gl = uigridlayout(d);
            gl.RowHeight = {40, '1x', 50};
            gl.ColumnWidth = {'1x', 120, 120};
            gl.Padding = [18 18 18 18];
            gl.RowSpacing = 12;
            gl.ColumnSpacing = 12;

            titleLabel = uilabel(gl);
            titleLabel.Text = 'Session summary';
            titleLabel.FontName = 'Times New Roman';
            titleLabel.FontSize = 20;
            titleLabel.FontWeight = 'bold';
            titleLabel.Layout.Row = 1;
            titleLabel.Layout.Column = [1 3];

            summaryBox = uitextarea(gl);
            summaryBox.Value = splitlines(summaryText);
            summaryBox.Editable = 'off';
            summaryBox.FontName = 'Consolas';
            summaryBox.FontSize = 13;
            summaryBox.Layout.Row = 2;
            summaryBox.Layout.Column = [1 3];

            closeButton = uibutton(gl, 'push');
            closeButton.Text = 'Close app';
            closeButton.FontName = 'Times New Roman';
            closeButton.FontSize = 14;
            closeButton.FontWeight = 'bold';
            closeButton.Layout.Row = 3;
            closeButton.Layout.Column = 2;
            closeButton.ButtonPushedFcn = @(src, event) confirmFinish();

            cancelButton = uibutton(gl, 'push');
            cancelButton.Text = 'Cancel';
            cancelButton.FontName = 'Times New Roman';
            cancelButton.FontSize = 14;
            cancelButton.Layout.Row = 3;
            cancelButton.Layout.Column = 3;
            cancelButton.ButtonPushedFcn = @(src, event) cancelFinish();

            uiwait(d);

            if isvalid(d)
                delete(d);
            end

            % If user cancels, show Home again.
            if ~closeNow && isvalid(app.UIFigure)
                app.UIFigure.Visible = oldHomeVisible;
                figure(app.UIFigure);
            end

            function confirmFinish()
                closeNow = true;
                uiresume(d);
            end

            function cancelFinish()
                closeNow = false;
                uiresume(d);
            end
        end


        function [latestFile, sessionFiles] = findSingleSessions(app)

            latestFile = "";
            sessionFiles = strings(0,1);


            % =========================================================
            % Resolve Single spectra output root
            % =========================================================
            singleRoot = fullfile( ...
                app.OutputRoot, ...
                'single');


            if ~isfolder(singleRoot)
                return
            end


            % =========================================================
            % Search all Single session files recursively
            % =========================================================
            listing = dir( ...
                fullfile( ...
                singleRoot, ...
                '**', ...
                'single_home_session.mat'));


            if isempty(listing)
                return
            end


            % =========================================================
            % Build absolute paths
            % =========================================================
            sessionFiles = strings(numel(listing), 1);

            for k = 1:numel(listing)

                sessionFiles(k) = string( ...
                    fullfile( ...
                    listing(k).folder, ...
                    listing(k).name));

            end


            % =========================================================
            % Latest modified session
            % =========================================================
            [~, idx] = max([listing.datenum]);

            latestFile = sessionFiles(idx);

        end


        function [action, resumeFile] = chooseSingleSessionAction(app)

            action = "Cancel";
            resumeFile = "";


            % =========================================================
            % Search existing sessions
            % =========================================================
            [latestFile, sessionFiles] = app.findSingleSessions();


            % =========================================================
            % No previous session
            % =========================================================
            if isempty(sessionFiles)

                action = "New";
                return

            end


            % =========================================================
            % Previous session exists
            % =========================================================
            choice = questdlg( ...
                sprintf([ ...
                'Previous Single spectra session(s) were found.\n\n' ...
                'What do you want to do?']), ...
                'Open Single spectra', ...
                'Start New', ...
                'Continue Last', ...
                'Browse Session', ...
                'Continue Last');


            if isempty(choice)
                action = "Cancel";
                return
            end


            switch choice

                case 'Start New'

                    action = "New";


                case 'Continue Last'

                    action = "Resume";
                    resumeFile = latestFile;


                case 'Browse Session'

                    startFolder = fullfile( ...
                        app.OutputRoot, ...
                        'single');

                    if ~isfolder(startFolder)
                        startFolder = pwd;
                    end


                    [file, path] = uigetfile( ...
                        { ...
                        'single_home_session.mat', ...
                        'Single spectra session'; ...
                        '*.mat', ...
                        'MAT files (*.mat)' ...
                        }, ...
                        'Select Single spectra session', ...
                        startFolder);


                    if isequal(file, 0)

                        action = "Cancel";
                        return

                    end


                    action = "Resume";
                    resumeFile = string(fullfile(path, file));

            end

        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.initializeHome();
        end

        % Value changed function: ModesDropDown
        function ModesDropDownValueChanged(app, event)
            app.initializeHome();

            app.setSelectedFolderFromMode();

            % Không hỏi login ở đây.
            % Chỉ chọn mode, chưa ghi history vì chưa có session.

        end

        % Button pushed function: AccessButton
        function AccessButtonPushed(app, event)
            app.initializeHome();

            if ~app.setSelectedFolderFromMode()

                uialert(app.UIFigure, ...
                    'Please select Single spectra or Raman scan.', ...
                    'Mode required');

                return
            end

            if ~app.requireLogin()
                return
            end

            if ~app.validateSelectedFolder()
                return
            end

            app.logSessionEvent( ...
                'Home', ...
                'Select mode', ...
                sprintf( ...
                'mode=%s; folder=%s', ...
                app.SelectedDataType, ...
                app.SelectedFolder));

            app.openNextApp();
        end

        % Value changed function: UserTextArea
        function UserTextAreaValueChanged(app, event)
            app.updateUserDisplay();

        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            app.initializeHome();

            if ~app.IsLoggedIn
                delete(app);
                return;
            end

            summaryText = app.buildSessionSummary(false);

            if app.askFinishConfirmation(summaryText)
                app.logSessionEvent('Home', 'Finish', ...
                    'Session closed by Finish button.');

                app.writeSessionSummaryFile();

                delete(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'batch_Layer 1.png');
            app.Image2 = uiimage(app.GridLayout);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'logo_icube.png');
            app.ModesDropDownLabel = uilabel(app.GridLayout);
            app.ModesDropDownLabel.Text = 'Modes';
            app.ModesDropDown = uidropdown(app.GridLayout);
            app.ModesDropDown.Items = {'', 'Single spectra', 'Raman scan'};
            app.ModesDropDown.ValueChangedFcn = createCallbackFcn(app, @ModesDropDownValueChanged, true);
            app.ModesDropDown.Value = '';
            app.AccessButton = uibutton(app.GridLayout, 'push');
            app.AccessButton.ButtonPushedFcn = createCallbackFcn(app, @AccessButtonPushed, true);
            app.AccessButton.Text = 'Access';
            app.UserTextAreaLabel = uilabel(app.GridLayout);
            app.UserTextAreaLabel.Text = 'User';
            app.UserTextArea = uitextarea(app.GridLayout);
            app.UserTextArea.ValueChangedFcn = createCallbackFcn(app, @UserTextAreaValueChanged, true);
            app.FinishButton = uibutton(app.GridLayout, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Home

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end