classdef Loadscan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        GridLayout7                matlab.ui.container.GridLayout
        Button_6                   matlab.ui.control.Button
        Button_5                   matlab.ui.control.Button
        Button_2                   matlab.ui.control.Button
        Button                     matlab.ui.control.Button
        GridLayout6                matlab.ui.container.GridLayout
        FinishButton               matlab.ui.control.Button
        GeneratescandataButton     matlab.ui.control.Button
        GridLayout5                matlab.ui.container.GridLayout
        MapcolorsDropDown          matlab.ui.control.DropDown
        MapcolorsDropDownLabel     matlab.ui.control.Label
        GridLayout4                matlab.ui.container.GridLayout
        WorkingmodesDropDown       matlab.ui.control.DropDown
        WorkingmodesDropDownLabel  matlab.ui.control.Label
        GridLayout3                matlab.ui.container.GridLayout
        ApplyfilterButton          matlab.ui.control.Button
        ResizescanButton           matlab.ui.control.Button
        BandplotButton             matlab.ui.control.Button
        GridLayout2                matlab.ui.container.GridLayout
        LoadRamanscanLabel         matlab.ui.control.Label
        LoadscanButton             matlab.ui.control.Button
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Parent application
        mainApp = []
        
        % Session received from scanspectra
        SessionInfo struct = struct()
        
        % RamanCube folders
        ProjectRoot char = ''
        DataInputRoot char = ''
        DataOutputRoot char = ''
        ScanInputRoot char = ''
        ScanOutputRoot char = ''
        ScanWorkspaceRoot char = ''
        
        
        % Generated result
        GeneratedMatPath char = ''
        GeneratedScanName char = ''
        
        % Input information
        SourceFolder string = ""
        HeaderFile string = ""
        XAxisFile string = ""
        YAxisFile string = ""
        TraceFile string = ""
        
        % Raw imported data
        HeaderText string = ""
        HeaderInfo struct = struct()
        Wavenumber double = []
        IntensityVector double = []
        
        % Raman data
        CubeOriginal double = []
        Cube double = []
        
        % Trace image
        TraceImage = []
        TraceImageOriginal = []
        
        % Current band
        CurrentBand double = [NaN NaN]
        CurrentBandIndices double = []
        CurrentMap double = []
        
        % Orientation record
        RotationQuarterTurns double = 0
        FlipVertical logical = false
        FlipHorizontal logical = false
        OrientationHistory cell = {}
        
        % Display settings
        CurrentColormap char = 'turbo'
        CurrentMode char = 'Trace - Band'
        
        % Hover information
        LastHoverRow double = NaN
        LastHoverColumn double = NaN
        HoverMarker = []
        HoverSpectrumLine = []
        
        % State
        IsLoaded logical = false
        
        % Mean spectrum
        MeanSpectrum double = []
        
        % Band filters
        BandFilters table = table( ...
            'Size', [0 6], ...
            'VariableTypes', ...
            {'double', 'string', 'double', 'double', 'string', 'double'}, ...
            'VariableNames', ...
            {'ID', 'Name', 'LowWN', 'HighWN', 'Method', 'PointCount'})
        
        CurrentFilterIndex double = NaN
        
        % Filter-manager window
        FilterFigure = []
        FilterAxes = []
        FilterTable = []
        FilterMethodDropDown = []
        FilterNameField = []
        FilterStatusLabel = []
        
        % Rectangles used to display saved filters
        FilterRectangleHandles = gobjects(0)
        
        % Lines used to highlight the currently selected filter
        CurrentFilterHighlightHandles = gobjects(0)
        
        
        % =========================================================
        % ACQUIRED REGION AND SPATIAL CROPPING
        % =========================================================
        
        % True only where a non-empty Raman spectrum was acquired.
        AcquiredMask2D logical = logical.empty
        
        % Records every spatial crop applied to the Raman cube.
        CropHistory cell = {}
        
        % Indicates that Cube is a spatial subset of CubeOriginal.
        IsSpatiallyCropped logical = false
        
        
        % =========================================================
        % FIXED RAMANCUBE FOLDERS
        % =========================================================
        
        RawScanRoot char = ''
        ReadableScanRoot char = ''
        
        % =========================================================
        % AUTOMATIC TRACE REGISTRATION
        % =========================================================
        
        AutoOrientationScore double = NaN
        AutoOrientationMethod char = ''
        AutoOrientationWasApplied logical = false
        
        % =========================================================
        % BAND-MAP FILTERING
        % =========================================================
        
        RawCurrentMap double = []
        FilteredCurrentMap double = []
        FilterWasApplied logical = false
        FilterSettings struct = struct()
        
        % Filter preview GUI
        ImageFilterFigure = []
        ImageFilterRawAxes = []
        ImageFilterResultAxes = []
        ImageFilterCorrelationAxes = []
        
        ImageFilterMedianSpinner = []
        ImageFilterGaussianSpinner = []
        ImageFilterBlendSpinner = []
        ImageFilterLowerSpinner = []
        ImageFilterUpperSpinner = []
        ImageFilterStatusLabel = []
        
        ImageFilterPreviewRaw double = []
        ImageFilterPreviewResult double = []
        ImageFilterPreviewCorrelation double = []
        LastPreparedPayload struct = struct()
    end
    
    
    methods (Access = public)
        
        function receiveScanHomeSession(app, scanSession, mainAppIn)
            
            % =========================================================
            % VALIDATE SESSION
            % =========================================================
            if nargin < 2 || ...
                    isempty(scanSession) || ...
                    ~isstruct(scanSession)
                
                error( ...
                    'Loadscan requires a valid Raman scan session.');
            end
            
            % =========================================================
            % STORE PARENT APP
            % =========================================================
            if nargin >= 3
                app.mainApp = mainAppIn;
            else
                app.mainApp = [];
            end
            
            app.SessionInfo = scanSession;
            
            % =========================================================
            % RECEIVE ROOT FOLDERS
            % =========================================================
            if isfield(scanSession, 'ProjectRoot')
                app.ProjectRoot = char(scanSession.ProjectRoot);
            end
            
            if isfield(scanSession, 'DataInputRoot')
                app.DataInputRoot = char(scanSession.DataInputRoot);
            elseif isfield(scanSession, 'InputRoot')
                app.DataInputRoot = char(scanSession.InputRoot);
            end
            
            if isfield(scanSession, 'DataOutputRoot')
                app.DataOutputRoot = char(scanSession.DataOutputRoot);
            elseif isfield(scanSession, 'OutputRoot')
                app.DataOutputRoot = char(scanSession.OutputRoot);
            end
            
            if isfield(scanSession, 'ScanInputRoot')
                app.ScanInputRoot = char(scanSession.ScanInputRoot);
            elseif ~isempty(app.DataInputRoot)
                app.ScanInputRoot = fullfile( ...
                    app.DataInputRoot, ...
                    'scan');
            end
            
            if isfield(scanSession, 'ScanOutputRoot')
                app.ScanOutputRoot = char(scanSession.ScanOutputRoot);
            elseif ~isempty(app.DataOutputRoot)
                app.ScanOutputRoot = fullfile( ...
                    app.DataOutputRoot, ...
                    'scan');
            end
            
            if isfield(scanSession, 'ScanWorkspaceRoot') && ...
                    ~isempty(scanSession.ScanWorkspaceRoot)
                
                app.ScanWorkspaceRoot = ...
                    char(scanSession.ScanWorkspaceRoot);
                
            elseif ~isempty(app.ScanOutputRoot)
                
                app.ScanWorkspaceRoot = fullfile( ...
                    app.ScanOutputRoot, ...
                    'workspaces');
            end
            
            app.RawScanRoot = fullfile( ...
                app.ScanInputRoot, ...
                'RAW');
            
            app.ReadableScanRoot = fullfile( ...
                app.ScanInputRoot, ...
                'READABLE');
            
            % =========================================================
            % FINAL VALIDATION
            % =========================================================
            if isempty(app.ScanInputRoot)
                
                error([ ...
                    'Loadscan did not receive ScanInputRoot ' ...
                    'from scanspectra.']);
            end
            
            if ~isfolder(app.ScanInputRoot)
                mkdir(app.ScanInputRoot);
            end
            
            foldersToCreate = { ...
                app.ScanOutputRoot, ...
                app.RawScanRoot, ...
                app.ReadableScanRoot, ...
                app.ScanWorkspaceRoot};
            
            for folderIndex = 1:numel(foldersToCreate)
                
                currentFolder = foldersToCreate{folderIndex};
                
                if ~isempty(currentFolder) && ...
                        ~isfolder(currentFolder)
                    
                    mkdir(currentFolder);
                end
            end
            
            % =========================================================
            % UPDATE APP DISPLAY
            % =========================================================
            userName = 'unknown_user';
            
            if isfield(scanSession, 'User') && ...
                    ~isempty(scanSession.User)
                
                userName = char(scanSession.User);
            end
            
            % app.TextArea.Value = { ...
            %     'PREPARE RAMAN SCAN READY'; ...
            %     '================================'; ...
            %     ''; ...
            %     ['User: ' userName]; ...
            %     ['Output folder: ' app.ScanInputRoot]; ...
            %     ''; ...
            %     'Press Load scan to import Raman acquisition files.'};
            
            app.UIFigure.Visible = 'on';
            
            try
                figure(app.UIFigure);
            catch
            end
        end
        
    end
    
    methods (Access = private)


        function percentileValue = ...
                manualPercentile(app, values, percentile)
            
            values = double(values(:));
            values = values(isfinite(values));
            
            if isempty(values)
                percentileValue = NaN;
                return;
            end
            
            values = sort(values, 'ascend');
            
            percentile = max(0, min(100, percentile));
            
            if numel(values) == 1
                percentileValue = values(1);
                return;
            end
            
            position = ...
                1 + (numel(values)-1) * percentile / 100;
            
            lowerIndex = floor(position);
            upperIndex = ceil(position);
            
            lowerIndex = max(1, min(numel(values), lowerIndex));
            upperIndex = max(1, min(numel(values), upperIndex));
            
            if lowerIndex == upperIndex
                
                percentileValue = values(lowerIndex);
                
            else
                
                interpolationWeight = position - lowerIndex;
                
                percentileValue = ...
                    values(lowerIndex) * ...
                    (1-interpolationWeight) + ...
                    values(upperIndex) * ...
                    interpolationWeight;
            end
            
        end
        
        
        function values = readNumericColumn(app, filePath)
            
            filePath = char(string(filePath));
            
            try
                values = load(filePath);
            catch ME
                error( ...
                    'Could not load numeric file:\n%s\n\n%s', ...
                    filePath, ...
                    ME.message);
            end
            
            if isstruct(values)
                
                fieldNames = fieldnames(values);
                
                if isempty(fieldNames)
                    error('The loaded file contains no numeric variable.');
                end
                
                values = values.(fieldNames{1});
            end
            
            if isempty(values) || ~isnumeric(values)
                error('The file does not contain numeric data:\n%s', filePath);
            end
            
            if size(values, 2) > 1
                
                validCount = sum(isfinite(values), 1);
                [~, bestColumn] = max(validCount);
                
                values = values(:, bestColumn);
            end
            
            values = values(:);
            values = values(isfinite(values));
            
            if isempty(values)
                error('The file contains no finite numeric values:\n%s', filePath);
            end
            
        end
        
        function info = parseHeaderFile(app, headerPath)
            
            textContent = fileread(headerPath);
            
            info = struct();
            
            info.FileName = extractHeaderString(app, textContent, 'FileName');
            info.GraphName = extractHeaderString(app, textContent, 'GraphName');
            
            info.SizeX = extractHeaderNumber(app, textContent, 'SizeX');
            info.SizeY = extractHeaderNumber(app, textContent, 'SizeY');
            info.SizeGraph = extractHeaderNumber(app, textContent, 'SizeGraph');
            
            info.ScanWidth = extractHeaderNumber(app, textContent, 'ScanWidth');
            info.ScanHeight = extractHeaderNumber(app, textContent, 'ScanHeight');
            
            info.ScanOriginX = extractHeaderNumber(app, textContent, 'ScanOriginX');
            info.ScanOriginY = extractHeaderNumber(app, textContent, 'ScanOriginY');
            info.ScanOriginZ = extractHeaderNumber(app, textContent, 'ScanOriginZ');
            
            info.ScanUnit = extractHeaderString(app, textContent, 'ScanUnit');
            info.XAxisUnit = extractHeaderString(app, textContent, 'XAxisUnit');
            info.DataUnit = extractHeaderString(app, textContent, 'DataUnit');
            
            requiredFields = {'SizeX', 'SizeY', 'SizeGraph'};
            
            for k = 1:numel(requiredFields)
                fieldName = requiredFields{k};
                
                if ~isfield(info, fieldName) || ...
                        isempty(info.(fieldName)) || ...
                        isnan(info.(fieldName))
                    
                    error('Header does not contain a valid %s value.', fieldName);
                end
            end
            
            info.SizeX = round(info.SizeX);
            info.SizeY = round(info.SizeY);
            info.SizeGraph = round(info.SizeGraph);
            
        end
        
        function value = extractHeaderNumber(app, textContent, key)
            
            expression = ['(?m)^\s*' regexptranslate('escape', key) ...
                '\s*=\s*([-+]?\d*\.?\d+(?:[eE][-+]?\d+)?)'];
            
            token = regexp(textContent, expression, 'tokens', 'once');
            
            if isempty(token)
                value = NaN;
            else
                value = str2double(token{1});
            end
            
        end


        function value = extractHeaderString(app, textContent, key)
            
            expression = ['(?m)^\s*' regexptranslate('escape', key) ...
                '\s*=\s*(.*?)\s*$'];
            
            token = regexp(textContent, expression, 'tokens', 'once');
            
            if isempty(token)
                value = "";
            else
                value = strtrim(string(token{1}));
            end
            
        end


        function validateImportedData(app)
            
            nX = app.HeaderInfo.SizeX;
            nY = app.HeaderInfo.SizeY;
            nGraph = app.HeaderInfo.SizeGraph;
            
            if numel(app.Wavenumber) ~= nGraph
                error([ ...
                    'X-axis length does not match SizeGraph.\n\n' ...
                    'X-axis length: %d\n' ...
                    'Header SizeGraph: %d'], ...
                    numel(app.Wavenumber), nGraph);
            end
            
            expectedYLength = nX * nY * nGraph;
            actualYLength = numel(app.IntensityVector);
            
            if actualYLength ~= expectedYLength
                error([ ...
                    'Y-axis length is inconsistent with Header dimensions.\n\n' ...
                    'Expected:\n' ...
                    'SizeX × SizeY × SizeGraph\n' ...
                    '= %d × %d × %d\n' ...
                    '= %d values\n\n' ...
                    'Actual Y-axis length: %d values'], ...
                    nX, nY, nGraph, expectedYLength, actualYLength);
            end
            
            if any(diff(app.Wavenumber) == 0)
                warning('The wavenumber axis contains duplicated values.');
            end
            
        end


        function reconstructCube(app)
            
            nX = app.HeaderInfo.SizeX;
            nY = app.HeaderInfo.SizeY;
            nGraph = app.HeaderInfo.SizeGraph;
            
            temporaryCube = reshape( ...
                app.IntensityVector, ...
                [nGraph, nX, nY]);
            
            temporaryCube = permute(temporaryCube, [2 3 1]);
            
            % Cube dimensions:
            % row × column × wavenumber
            app.CubeOriginal = temporaryCube;
            app.Cube = temporaryCube;
            
        end


        function refreshDisplay(app)
            
            cla(app.UIAxes, 'reset');
            cla(app.UIAxes2, 'reset');
            
            app.HoverMarker = [];
            app.HoverSpectrumLine = [];
            
            app.UIAxes.Box = 'on';
            app.UIAxes2.Box = 'on';
            
            if ~app.IsLoaded
                title(app.UIAxes, 'Trace');
                title(app.UIAxes2, 'Raman band');
                return;
            end
            
            switch app.CurrentMode
                
                case 'Trace - Band'
                    
                    displayTrace(app, app.UIAxes);
                    
                    if isempty(app.CurrentMap)
                        
                        title(app.UIAxes2, 'Raman band');
                        
                        text(app.UIAxes2, 0.5, 0.5, ...
                            'Click Band filters and select a filter', ...
                            'HorizontalAlignment', 'center', ...
                            'Units', 'normalized');
                        
                    else
                        
                        displayBandMap(app, app.UIAxes2);
                        
                    end
                case 'Band - Spectra'
                    
                    if isempty(app.CurrentMap) && ...
                            ~isempty(app.BandFilters) && ...
                            ~isnan(app.CurrentFilterIndex)
                        
                        try
                            calculateCurrentFilterMap(app);
                        catch
                        end
                    end
                    
                    if isempty(app.CurrentMap)
                        title(app.UIAxes, 'Raman band');
                    else
                        displayBandMap(app, app.UIAxes);
                    end
                    
                    prepareSpectrumAxis(app, app.UIAxes2);
                    
                case 'Trace - Spectra'
                    
                    displayTrace(app, app.UIAxes);
                    prepareSpectrumAxis(app, app.UIAxes2);
                    
                otherwise
                    
                    displayTrace(app, app.UIAxes);
                    
                    if ~isempty(app.CurrentMap)
                        displayBandMap(app, app.UIAxes2);
                    end
            end
            
        end


        function displayTrace(app, targetAxes)
            
            cla(targetAxes);
            
            if isempty(app.TraceImage)
                
                targetAxes.XLim = [0.5 size(app.Cube, 2) + 0.5];
                targetAxes.YLim = [0.5 size(app.Cube, 1) + 0.5];
                targetAxes.YDir = 'reverse';
                
                text(targetAxes, 0.5, 0.5, ...
                    'Trace image not available', ...
                    'Units', 'normalized', ...
                    'HorizontalAlignment', 'center');
                
                title(targetAxes, 'Trace');
                return;
            end
            
            imageHandle = imshow(app.TraceImage, ...
                'Parent', targetAxes);
            
            imageHandle.HitTest = 'off';
            imageHandle.PickableParts = 'none';
            
            axis(targetAxes, 'image');
            targetAxes.XTick = [];
            targetAxes.YTick = [];
            targetAxes.Box = 'on';
            
            title(targetAxes, 'Trace');
            
        end


        function displayBandMap(app, targetAxes)
            
            cla(targetAxes);
            
            if isempty(app.CurrentMap)
                return;
            end
            
            % =========================================================
            % CREATE DISPLAY-ONLY ENHANCED MAP
            % Does not modify app.CurrentMap
            % =========================================================
            [displayMap, displayMask] = ...
                prepareBandMapForDisplay(app, app.CurrentMap);
            
            % =========================================================
            % SHOW IMAGE
            % =========================================================
            imageHandle = imagesc( ...
                targetAxes, ...
                displayMap, ...
                [0 1]);
            
            imageHandle.HitTest = 'off';
            imageHandle.PickableParts = 'none';
            
            % Make rejected/background pixels transparent.
            % The axes background underneath is black.
            imageHandle.AlphaData = double(displayMask);
            
            axis(targetAxes, 'image');
            
            targetAxes.YDir = 'reverse';
            targetAxes.XTick = [];
            targetAxes.YTick = [];
            targetAxes.Box = 'on';
            
            % Black visual background
            targetAxes.Color = [0 0 0];
            targetAxes.CLim = [0 1];
            
            applyCurrentColormap(app, targetAxes);
            
            % =========================================================
            % TITLE
            % =========================================================
            if ~isempty(app.BandFilters) && ...
                    ~isnan(app.CurrentFilterIndex) && ...
                    app.CurrentFilterIndex <= height(app.BandFilters)
                
                currentFilter = ...
                    app.BandFilters(app.CurrentFilterIndex, :);
                
                titleText = sprintf( ...
                    '%s | %.2f-%.2f cm^{-1} | %s', ...
                    currentFilter.Name, ...
                    currentFilter.LowWN, ...
                    currentFilter.HighWN, ...
                    currentFilter.Method);
                
            else
                
                titleText = sprintf( ...
                    'Raman band: %.2f-%.2f cm^{-1}', ...
                    app.CurrentBand(1), ...
                    app.CurrentBand(2));
            end
            
            title(targetAxes, titleText);
            
        end


        function applyCurrentColormap(app, targetAxes)
            
            switch lower(app.CurrentColormap)
                
                case 'turbo'
                    
                    baseMap = turbo(256);
                    
                    % Force the lowest colors toward black
                    blackCount = 20;
                    
                    fade = linspace(0, 1, blackCount)';
                    
                    baseMap(1:blackCount, :) = ...
                        baseMap(1:blackCount, :) .* fade;
                    
                    baseMap(1, :) = [0 0 0];
                    
                    colormap(targetAxes, baseMap);
                    
                case 'jet'
                    
                    baseMap = jet(256);
                    
                    blackCount = 22;
                    fade = linspace(0, 1, blackCount)';
                    
                    baseMap(1:blackCount, :) = ...
                        baseMap(1:blackCount, :) .* fade;
                    
                    baseMap(1, :) = [0 0 0];
                    
                    colormap(targetAxes, baseMap);
                    
                case 'hot'
                    
                    % MATLAB hot already begins close to black.
                    % Add an exact black region for the background.
                    baseMap = hot(256);
                    
                    blackCount = 12;
                    
                    baseMap(1:blackCount, :) = repmat( ...
                        [0 0 0], ...
                        blackCount, ...
                        1);
                    
                    colormap(targetAxes, baseMap);
                    
                case 'gray'
                    
                    colormap(targetAxes, gray(256));
                    
                case 'parula'
                    
                    baseMap = parula(256);
                    
                    blackCount = 20;
                    fade = linspace(0, 1, blackCount)';
                    
                    baseMap(1:blackCount, :) = ...
                        baseMap(1:blackCount, :) .* fade;
                    
                    baseMap(1, :) = [0 0 0];
                    
                    colormap(targetAxes, baseMap);
                    
                case 'bone'
                    
                    colormap(targetAxes, bone(256));
                    
                otherwise
                    
                    colormap(targetAxes, hot(256));
            end
            
        end
        
        
        function prepareSpectrumAxis(app, targetAxes)
            
            cla(targetAxes);
            
            targetAxes.Box = 'on';
            targetAxes.XGrid = 'on';
            targetAxes.YGrid = 'on';
            
            xlabel(targetAxes, 'Raman shift (cm^{-1})');
            ylabel(targetAxes, 'Intensity');
            
            title(targetAxes, 'Move cursor over image');
            
            if ~isempty(app.Wavenumber)
                targetAxes.XLim = [ ...
                    min(app.Wavenumber), ...
                    max(app.Wavenumber)];
            end
            
        end
        
        
        function bandAxes = getCurrentBandAxes(app)
            
            bandAxes = [];
            
            switch app.CurrentMode
                
                case 'Trace - Band'
                    
                    % Axis 1 = trace
                    % Axis 2 = Raman band
                    bandAxes = ...
                        app.UIAxes2;
                    
                case 'Band - Spectra'
                    
                    % Axis 1 = Raman band
                    % Axis 2 = spectrum
                    bandAxes = ...
                        app.UIAxes;
                    
                otherwise
                    
                    % Trace - Spectra does not currently show a band map.
                    bandAxes = [];
            end
            
        end


        function synchronizeIntensityVectorFromCube(app)
            
            if isempty(app.Cube)
                
                app.IntensityVector = [];
                return;
            end
            
            % Reverse of reconstructCube:
            %
            % reconstructCube:
            %   reshape vector -> [nWn, nX, nY]
            %   permute         -> [nX, nY, nWn]
            %
            % Here:
            %   [row, col, wn] -> [wn, row, col] -> vector
            
            temporaryCube = ...
                permute( ...
                app.Cube, ...
                [3 1 2]);
            
            app.IntensityVector = ...
                temporaryCube(:);
            
        end
        
        
        function figureMouseMoved(app)
            
            if ~app.IsLoaded || isempty(app.Cube)
                return;
            end
            
            switch app.CurrentMode
                
                case 'Band - Spectra'
                    
                    if isempty(app.CurrentMap)
                        return;
                    end
                    
                    sourceAxes = app.UIAxes;
                    spectrumAxes = app.UIAxes2;
                    sourceType = 'band';
                    
                case 'Trace - Spectra'
                    
                    if isempty(app.TraceImage)
                        return;
                    end
                    
                    sourceAxes = app.UIAxes;
                    spectrumAxes = app.UIAxes2;
                    sourceType = 'trace';
                    
                otherwise
                    return;
            end
            
            if ~isPointerInsideAxes(app, sourceAxes)
                return;
            end
            
            point = sourceAxes.CurrentPoint;
            
            xValue = point(1,1);
            yValue = point(1,2);
            
            nRows = size(app.Cube, 1);
            nColumns = size(app.Cube, 2);
            
            switch sourceType
                
                case 'band'
                    
                    column = round(xValue);
                    row = round(yValue);
                    
                case 'trace'
                    
                    traceHeight = size(app.TraceImage, 1);
                    traceWidth = size(app.TraceImage, 2);
                    
                    % Convert trace-image coordinate to cube coordinate
                    normalizedX = (xValue - 0.5) / traceWidth;
                    normalizedY = (yValue - 0.5) / traceHeight;
                    
                    column = floor(normalizedX * nColumns) + 1;
                    row = floor(normalizedY * nRows) + 1;
            end
            
            if row < 1 || row > nRows || ...
                    column < 1 || column > nColumns
                return;
            end
            
            if isequal(row, app.LastHoverRow) && ...
                    isequal(column, app.LastHoverColumn)
                return;
            end
            
            app.LastHoverRow = row;
            app.LastHoverColumn = column;
            
            plotSpectrumAtPixel(app, row, column, spectrumAxes);
            showHoverMarker(app, sourceAxes, row, column, sourceType);
            
            drawnow limitrate;
            
        end
        
        function inside = isPointerInsideAxes(app, targetAxes)
            
            if isempty(targetAxes) || ~isvalid(targetAxes)
                inside = false;
                return;
            end
            
            point = targetAxes.CurrentPoint;
            
            xValue = point(1,1);
            yValue = point(1,2);
            
            xLimits = targetAxes.XLim;
            yLimits = targetAxes.YLim;
            
            inside = ...
                xValue >= min(xLimits) && ...
                xValue <= max(xLimits) && ...
                yValue >= min(yLimits) && ...
                yValue <= max(yLimits);
            
        end
        
        
        function plotSpectrumAtPixel(app, row, column, targetAxes)
            
            spectrum = squeeze(app.Cube(row, column, :));
            spectrum = spectrum(:);
            
            if numel(spectrum) ~= numel(app.Wavenumber)
                return;
            end
            
            if isempty(app.HoverSpectrumLine) || ...
                    ~isgraphics(app.HoverSpectrumLine)
                
                cla(targetAxes);
                
                app.HoverSpectrumLine = plot( ...
                    targetAxes, ...
                    app.Wavenumber, ...
                    spectrum, ...
                    'LineWidth', 1.1);
                
                xlabel(targetAxes, 'Raman shift (cm^{-1})');
                ylabel(targetAxes, 'Intensity');
                
                targetAxes.Box = 'on';
                targetAxes.XGrid = 'on';
                targetAxes.YGrid = 'on';
                
                targetAxes.XLim = ...
                    [min(app.Wavenumber), max(app.Wavenumber)];
                
            else
                
                app.HoverSpectrumLine.XData = app.Wavenumber;
                app.HoverSpectrumLine.YData = spectrum;
                
            end
            
            title(targetAxes, ...
                sprintf('Spectrum — row %d, column %d', ...
                row, column));
            
            finiteValues = spectrum(isfinite(spectrum));
            
            if ~isempty(finiteValues)
                
                minimumValue = min(finiteValues);
                maximumValue = max(finiteValues);
                
                if maximumValue > minimumValue
                    
                    margin = 0.05 * ...
                        (maximumValue - minimumValue);
                    
                    targetAxes.YLim = ...
                        [minimumValue-margin, maximumValue+margin];
                end
            end
            
        end


        function showHoverMarker(app, targetAxes, row, column, sourceType)
            
            if ~isempty(app.HoverMarker) && ...
                    isgraphics(app.HoverMarker)
                
                delete(app.HoverMarker);
            end
            
            switch sourceType
                
                case 'band'
                    
                    markerX = column;
                    markerY = row;
                    
                case 'trace'
                    
                    traceHeight = size(app.TraceImage, 1);
                    traceWidth = size(app.TraceImage, 2);
                    
                    markerX = ...
                        (column - 0.5) / size(app.Cube, 2) * ...
                        traceWidth + 0.5;
                    
                    markerY = ...
                        (row - 0.5) / size(app.Cube, 1) * ...
                        traceHeight + 0.5;
                    
                otherwise
                    return;
            end
            
            hold(targetAxes, 'on');
            
            app.HoverMarker = plot( ...
                targetAxes, ...
                markerX, ...
                markerY, ...
                'o', ...
                'MarkerSize', 9, ...
                'LineWidth', 1.5, ...
                'MarkerFaceColor', 'none');
            
            hold(targetAxes, 'off');
            
        end
        
        
        function recalculateCurrentMapAfterOrientation(app)
            
            if isempty(app.BandFilters) || ...
                    isnan(app.CurrentFilterIndex)
                
                app.CurrentMap = [];
                return;
            end
            
            calculateCurrentFilterMap(app);
            
        end
        
        
        function RamanData = buildRamanDataStructure(app, outputFolder)
            
            outputFolder = char(string(outputFolder));
            
            RamanData = struct();
            
            % =============================================================
            % FORMAT / STORAGE POLICY
            % =============================================================
            
            RamanData.SchemaVersion = 2;
            
            RamanData.FormatVersion = ...
                'RamanCube Scan MAT v2.0';
            
            RamanData.StoragePolicy = ...
                'single_canonical_cube';
            
            RamanData.DataType = ...
                'Raman scan';
            
            RamanData.Stage = ...
                'Prepared raw scan';
            
            % =============================================================
            % SOURCE PATHS
            % =============================================================
            
            RamanData.SourceFolder = ...
                char(app.SourceFolder);
            
            RamanData.OutputFolder = ...
                outputFolder;
            
            RamanData.HeaderFile = ...
                char(app.HeaderFile);
            
            RamanData.XAxisFile = ...
                char(app.XAxisFile);
            
            RamanData.YAxisFile = ...
                char(app.YAxisFile);
            
            RamanData.TraceFile = ...
                char(app.TraceFile);
            
            % =============================================================
            % HEADER
            % =============================================================
            
            RamanData.HeaderText = ...
                char(app.HeaderText);
            
            RamanData.HeaderInfo = ...
                app.HeaderInfo;
            
            % =============================================================
            % CANONICAL SPECTRAL DATA
            %
            % IMPORTANT:
            %
            % Only ONE complete spectral representation is stored.
            %
            % DO NOT save:
            %   RamanData.IntensityVector
            %   RamanData.CubeOriginal
            %
            % Cube is the authoritative prepared Raman cube.
            % =============================================================
            
            RamanData.Wavenumber = ...
                double(app.Wavenumber(:));
            
            RamanData.Cube = ...
                app.Cube;
            
            % =============================================================
            % TRACE IMAGE
            %
            % 2-D images are small relative to the Raman cube and may remain.
            % =============================================================
            
            RamanData.TraceImageOriginal = ...
                app.TraceImageOriginal;
            
            RamanData.TraceImage = ...
                app.TraceImage;
            
            % =============================================================
            % CURRENT DISPLAY / FILTER METADATA
            % =============================================================
            
            RamanData.CurrentBand = ...
                app.CurrentBand;
            
            RamanData.CurrentBandIndices = ...
                app.CurrentBandIndices;
            
            RamanData.CurrentMap = ...
                app.CurrentMap;
            
            RamanData.MeanSpectrum = ...
                app.MeanSpectrum;
            
            RamanData.BandFilters = ...
                app.BandFilters;
            
            RamanData.CurrentFilterIndex = ...
                app.CurrentFilterIndex;
            
            % =============================================================
            % ORIENTATION
            % =============================================================
            
            RamanData.Orientation = struct();
            
            RamanData.Orientation.RotationQuarterTurns = ...
                app.RotationQuarterTurns;
            
            RamanData.Orientation.RotationDegrees = ...
                app.RotationQuarterTurns * 90;
            
            RamanData.Orientation.FlipVertical = ...
                app.FlipVertical;
            
            RamanData.Orientation.FlipHorizontal = ...
                app.FlipHorizontal;
            
            RamanData.Orientation.History = ...
                app.OrientationHistory;
            
            RamanData.Orientation.OutputCubeIsOriented = ...
                true;
            
            % =============================================================
            % DISPLAY
            % =============================================================
            
            RamanData.Colormap = ...
                char(app.CurrentColormap);
            
            RamanData.WorkingMode = ...
                char(app.CurrentMode);
            
            % =============================================================
            % DIMENSIONS
            % =============================================================
            
            [ny, nx, nWn] = ...
                size(RamanData.Cube);
            
            RamanData.SizeRows = ...
                ny;
            
            RamanData.SizeColumns = ...
                nx;
            
            RamanData.SizeGraph = ...
                nWn;
            
            RamanData.NumberOfSpectra = ...
                ny * nx;
            
            RamanData.IsOptimized = ...
                true;
            
            % =============================================================
            % SCAN IDENTITY
            % =============================================================
            
            [~, scanFolderName] = ...
                fileparts(outputFolder);
            
            RamanData.ScanName = ...
                scanFolderName;
            
            RamanData.ScanID = ...
                regexprep( ...
                scanFolderName, ...
                '[^\w\-]', ...
                '_');
            
            % =============================================================
            % VALID PIXEL MASK
            % =============================================================
            
            if ~isempty(app.AcquiredMask2D) && ...
                    isequal(size(app.AcquiredMask2D), [ny nx])
                
                RamanData.ValidMask = ...
                    logical(app.AcquiredMask2D) & ...
                    all(isfinite(RamanData.Cube), 3);
                
            else
                
                spectralMagnitude = ...
                    max(abs(RamanData.Cube), [], 3);
                
                RamanData.ValidMask = ...
                    spectralMagnitude > 0 & ...
                    all(isfinite(RamanData.Cube), 3);
            end
            
            % =============================================================
            % PARENT SESSION
            % =============================================================
            
            RamanData.SessionID = '';
            RamanData.PreparedBy = '';
            
            if ~isempty(app.SessionInfo)
                
                if isfield(app.SessionInfo, 'SessionID')
                    
                    RamanData.SessionID = ...
                        char(string(app.SessionInfo.SessionID));
                end
                
                if isfield(app.SessionInfo, 'User')
                    
                    RamanData.PreparedBy = ...
                        char(string(app.SessionInfo.User));
                end
            end
            
            % =============================================================
            % STORAGE METADATA
            % =============================================================
            
            RamanData.Storage = struct();
            
            RamanData.Storage.CanonicalSpectralField = ...
                'Cube';
            
            RamanData.Storage.IntensityVectorSaved = ...
                false;
            
            RamanData.Storage.DuplicateOriginalCubeSaved = ...
                false;
            
            RamanData.Storage.Note = ...
                ['The prepared Cube is the only complete spectral array ' ...
                'stored in this READABLE MAT file.'];
            
            RamanData.CreatedAt = ...
                datetime('now');
            
        end
        
        function meanSpectrum = calculateMeanSpectrum(app)
            
            if isempty(app.Cube)
                
                meanSpectrum = [];
                return;
            end
            
            [ny, nx, nWavenumbers] = ...
                size(app.Cube);
            
            spectraMatrix = ...
                reshape( ...
                double(app.Cube), ...
                ny * nx, ...
                nWavenumbers);
            
            if ~isempty(app.AcquiredMask2D) && ...
                    isequal(size(app.AcquiredMask2D), [ny nx])
                
                validRows = ...
                    app.AcquiredMask2D(:);
                
            else
                
                validRows = ...
                    any(abs(spectraMatrix) > 0, 2);
            end
            
            validRows = ...
                validRows & ...
                all(isfinite(spectraMatrix), 2);
            
            if ~any(validRows)
                
                meanSpectrum = ...
                    nan(nWavenumbers, 1);
                
                return;
            end
            
            meanSpectrum = ...
                mean( ...
                spectraMatrix(validRows, :), ...
                1, ...
                'omitnan');
            
            meanSpectrum = ...
                meanSpectrum(:);
            
        end
        
        
        function openBandFilterManager(app)
            
            % If the window already exists, bring it forward
            if ~isempty(app.FilterFigure) && isvalid(app.FilterFigure)
                app.FilterFigure.Visible = 'on';
                figure(app.FilterFigure);
                refreshFilterManager(app);
                return;
            end
            
            app.FilterFigure = uifigure( ...
                'Name', 'Raman band filter manager', ...
                'Position', [150 100 1050 650], ...
                'CloseRequestFcn', ...
                @(src,event) closeFilterManager(app));
            
            mainGrid = uigridlayout(app.FilterFigure, [3 1]);
            
            mainGrid.RowHeight = {'1x', 185, 55};
            mainGrid.ColumnWidth = {'1x'};
            mainGrid.Padding = [15 15 15 15];
            mainGrid.RowSpacing = 10;
            
            %% Mean spectrum axis
            app.FilterAxes = uiaxes(mainGrid);
            app.FilterAxes.Layout.Row = 1;
            app.FilterAxes.Layout.Column = 1;
            
            app.FilterAxes.Box = 'on';
            app.FilterAxes.XGrid = 'on';
            app.FilterAxes.YGrid = 'on';
            
            xlabel(app.FilterAxes, 'Raman shift (cm^{-1})');
            ylabel(app.FilterAxes, 'Mean intensity');
            title(app.FilterAxes, ...
                'Mean spectrum – draw a rectangle to define a Raman band');
            
            %% Filter table
            app.FilterTable = uitable(mainGrid);
            app.FilterTable.Layout.Row = 2;
            app.FilterTable.Layout.Column = 1;
            
            app.FilterTable.ColumnName = ...
                {'ID', 'Filter name', 'Low WN', 'High WN', ...
                'Method', 'Points'};
            
            app.FilterTable.ColumnEditable = ...
                [false true true true true false];
            
            app.FilterTable.ColumnWidth = ...
                {45, 180, 100, 100, 90, 70};
            
            app.FilterTable.CellSelectionCallback = ...
                @(src,event) filterTableSelectionChanged(app, event);
            
            app.FilterTable.CellEditCallback = ...
                @(src,event) filterTableEdited(app, event);
            
            %% Controls
            controlGrid = uigridlayout(mainGrid, [1 8]);
            
            controlGrid.Layout.Row = 3;
            controlGrid.Layout.Column = 1;
            
            controlGrid.ColumnWidth = ...
                {90, 130, 85, 170, 140, 120, '1x', 90};
            
            controlGrid.RowHeight = {'1x'};
            controlGrid.ColumnSpacing = 8;
            
            methodLabel = uilabel(controlGrid);
            methodLabel.Text = 'Calculation:';
            methodLabel.HorizontalAlignment = 'right';
            methodLabel.Layout.Column = 1;
            
            app.FilterMethodDropDown = uidropdown(controlGrid);
            app.FilterMethodDropDown.Items = {'Sum', 'Average'};
            app.FilterMethodDropDown.Value = 'Average';
            app.FilterMethodDropDown.Layout.Column = 2;
            
            nameLabel = uilabel(controlGrid);
            nameLabel.Text = 'Name:';
            nameLabel.HorizontalAlignment = 'right';
            nameLabel.Layout.Column = 3;
            
            app.FilterNameField = uieditfield(controlGrid, 'text');
            app.FilterNameField.Value = '';
            app.FilterNameField.Placeholder = 'Optional filter name';
            app.FilterNameField.Layout.Column = 4;
            
            drawButton = uibutton(controlGrid, 'push');
            drawButton.Text = 'Draw new filter';
            drawButton.Layout.Column = 5;
            drawButton.ButtonPushedFcn = ...
                @(src,event) drawNewBandFilter(app);
            
            deleteButton = uibutton(controlGrid, 'push');
            deleteButton.Text = 'Delete filter';
            deleteButton.Layout.Column = 6;
            deleteButton.ButtonPushedFcn = ...
                @(src,event) deleteSelectedBandFilter(app);
            
            app.FilterStatusLabel = uilabel(controlGrid);
            app.FilterStatusLabel.Text = 'Ready';
            app.FilterStatusLabel.Layout.Column = 7;
            
            closeButton = uibutton(controlGrid, 'push');
            closeButton.Text = 'Close';
            closeButton.Layout.Column = 8;
            closeButton.ButtonPushedFcn = ...
                @(src,event) closeFilterManager(app);
            
            refreshFilterManager(app);
            
        end
        
        function closeFilterManager(app)
            
            if ~isempty(app.FilterFigure) && isvalid(app.FilterFigure)
                delete(app.FilterFigure);
            end
            
            app.FilterFigure = [];
            app.FilterAxes = [];
            app.FilterTable = [];
            app.FilterMethodDropDown = [];
            app.FilterNameField = [];
            app.FilterStatusLabel = [];
            app.FilterRectangleHandles = gobjects(0);
            
        end


        function refreshFilterManager(app)
            
            if isempty(app.FilterFigure) || ~isvalid(app.FilterFigure)
                return;
            end
            
            if isempty(app.FilterAxes) || ~isvalid(app.FilterAxes)
                return;
            end
            
            cla(app.FilterAxes);
            
            plot( ...
                app.FilterAxes, ...
                app.Wavenumber, ...
                app.MeanSpectrum, ...
                'LineWidth', 1.2);
            
            app.FilterAxes.Box = 'on';
            app.FilterAxes.XGrid = 'on';
            app.FilterAxes.YGrid = 'on';
            
            xlabel(app.FilterAxes, 'Raman shift (cm^{-1})');
            ylabel(app.FilterAxes, 'Mean intensity');
            
            title(app.FilterAxes, ...
                'Mean spectrum – draw a rectangle to define a Raman band');
            
            if ~isempty(app.Wavenumber)
                app.FilterAxes.XLim = ...
                    [min(app.Wavenumber), max(app.Wavenumber)];
            end
            
            if ~isempty(app.FilterTable) && isvalid(app.FilterTable)
                app.FilterTable.Data = app.BandFilters;
            end
            
            drawSavedFilterRectangles(app);
            
        end


        function drawSavedFilterRectangles(app)
            
            if isempty(app.FilterAxes) || ~isvalid(app.FilterAxes)
                return;
            end
            
            % Delete old boundary lines and labels
            if ~isempty(app.FilterRectangleHandles)
                
                validHandles = isgraphics(app.FilterRectangleHandles);
                
                if any(validHandles)
                    delete(app.FilterRectangleHandles(validHandles));
                end
            end
            
            app.FilterRectangleHandles = gobjects(0);
            
            if isempty(app.BandFilters)
                return;
            end
            
            yLimits = app.FilterAxes.YLim;
            labelY = yLimits(2) - 0.03 * diff(yLimits);
            
            hold(app.FilterAxes, 'on');
            
            for filterIndex = 1:height(app.BandFilters)
                
                lowWN = app.BandFilters.LowWN(filterIndex);
                highWN = app.BandFilters.HighWN(filterIndex);
                filterName = char(app.BandFilters.Name(filterIndex));
                
                % Left boundary
                leftLine = xline( ...
                    app.FilterAxes, ...
                    lowWN, ...
                    '--', ...
                    'LineWidth', 1.2, ...
                    'HitTest', 'off', ...
                    'PickableParts', 'none');
                
                % Right boundary
                rightLine = xline( ...
                    app.FilterAxes, ...
                    highWN, ...
                    '--', ...
                    'LineWidth', 1.2, ...
                    'HitTest', 'off', ...
                    'PickableParts', 'none');
                
                % Filter label
                labelHandle = text( ...
                    app.FilterAxes, ...
                    mean([lowWN highWN]), ...
                    labelY, ...
                    filterName, ...
                    'HorizontalAlignment', 'center', ...
                    'VerticalAlignment', 'top', ...
                    'FontSize', 9, ...
                    'Clipping', 'on', ...
                    'HitTest', 'off', ...
                    'PickableParts', 'none');
                
                app.FilterRectangleHandles(end+1) = leftLine;
                app.FilterRectangleHandles(end+1) = rightLine;
                app.FilterRectangleHandles(end+1) = labelHandle;
            end
            
            hold(app.FilterAxes, 'off');
            
        end
        
        
        function drawNewBandFilter(app)
            
            if isempty(app.FilterAxes) || ~isvalid(app.FilterAxes)
                return;
            end
            
            app.FilterStatusLabel.Text = ...
                'Draw and adjust the rectangle, then double-click to confirm';
            
            drawnow;
            
            roi = [];
            
            try
                drawingArea = [ ...
                    min(app.Wavenumber), ...
                    app.FilterAxes.YLim(1), ...
                    range(app.Wavenumber), ...
                    range(app.FilterAxes.YLim)];
                
                roi = drawrectangle( ...
                    app.FilterAxes, ...
                    'DrawingArea', drawingArea, ...
                    'FaceAlpha', 0, ...
                    'LineWidth', 1.5, ...
                    'InteractionsAllowed', 'all');
                
                % Allow the user to resize and reposition the ROI.
                % Double-click confirms the final position.
                wait(roi);
                
                if isempty(roi) || ~isvalid(roi)
                    app.FilterStatusLabel.Text = 'Selection cancelled';
                    return;
                end
                
                position = roi.Position;
                
                lowRequested = position(1);
                highRequested = position(1) + position(3);
                
                if lowRequested > highRequested
                    temporary = lowRequested;
                    lowRequested = highRequested;
                    highRequested = temporary;
                end
                
                lowRequested = max( ...
                    lowRequested, ...
                    min(app.Wavenumber));
                
                highRequested = min( ...
                    highRequested, ...
                    max(app.Wavenumber));
                
                delete(roi);
                roi = [];
                
                selectedIndices = find( ...
                    app.Wavenumber >= lowRequested & ...
                    app.Wavenumber <= highRequested);
                
                if isempty(selectedIndices)
                    
                    centerWN = mean([lowRequested highRequested]);
                    
                    [~, nearestIndex] = min( ...
                        abs(app.Wavenumber-centerWN));
                    
                    selectedIndices = nearestIndex;
                end
                
                actualLowWN = ...
                    app.Wavenumber(selectedIndices(1));
                
                actualHighWN = ...
                    app.Wavenumber(selectedIndices(end));
                
                filterMethod = string( ...
                    app.FilterMethodDropDown.Value);
                
                filterName = strtrim( ...
                    string(app.FilterNameField.Value));
                
                if strlength(filterName) == 0
                    filterName = ...
                        "Filter " + string(height(app.BandFilters)+1);
                end
                
                if isempty(app.BandFilters)
                    newID = 1;
                else
                    newID = max(app.BandFilters.ID)+1;
                end
                
                newFilter = table( ...
                    newID, ...
                    filterName, ...
                    actualLowWN, ...
                    actualHighWN, ...
                    filterMethod, ...
                    numel(selectedIndices), ...
                    'VariableNames', ...
                    {'ID', 'Name', 'LowWN', 'HighWN', ...
                    'Method', 'PointCount'});
                
                app.BandFilters = ...
                    [app.BandFilters; newFilter];
                
                app.CurrentFilterIndex = ...
                    height(app.BandFilters);
                
                app.FilterNameField.Value = '';
                
                calculateCurrentFilterMap(app);
                updateSelectedFilterInformation(app);
                
                refreshDisplay(app);
                refreshFilterManager(app);
                highlightCurrentFilter(app);
                
                app.FilterStatusLabel.Text = sprintf( ...
                    'Created %s: %.2f–%.2f cm^{-1}', ...
                    char(filterName), ...
                    actualLowWN, ...
                    actualHighWN);
                
            catch ME
                
                if ~isempty(roi) && isvalid(roi)
                    delete(roi);
                end
                
                if ~isempty(app.FilterStatusLabel) && ...
                        isvalid(app.FilterStatusLabel)
                    
                    app.FilterStatusLabel.Text = ...
                        'Could not create filter';
                end
                
                uialert( ...
                    app.FilterFigure, ...
                    ME.message, ...
                    'Filter creation error', ...
                    'Icon', 'error');
            end
            
        end
        
        
        function calculateCurrentFilterMap(app)
            
            if isempty(app.BandFilters) || ...
                    isnan(app.CurrentFilterIndex)
                
                app.CurrentMap = [];
                app.CurrentBand = [NaN NaN];
                app.CurrentBandIndices = [];
                return;
            end
            
            filterIndex = app.CurrentFilterIndex;
            
            if filterIndex < 1 || ...
                    filterIndex > height(app.BandFilters)
                
                app.CurrentMap = [];
                return;
            end
            
            lowWN = app.BandFilters.LowWN(filterIndex);
            highWN = app.BandFilters.HighWN(filterIndex);
            method = app.BandFilters.Method(filterIndex);
            
            selectedIndices = find( ...
                app.Wavenumber >= lowWN & ...
                app.Wavenumber <= highWN);
            
            if isempty(selectedIndices)
                
                centerWN = mean([lowWN highWN]);
                
                [~, nearestIndex] = min( ...
                    abs(app.Wavenumber - centerWN));
                
                selectedIndices = nearestIndex;
            end
            
            app.CurrentBand = [lowWN highWN];
            app.CurrentBandIndices = selectedIndices;
            
            selectedCube = app.Cube(:, :, selectedIndices);
            
            switch lower(strtrim(method))
                
                case "sum"
                    
                    app.CurrentMap = sum( ...
                        selectedCube, ...
                        3, ...
                        'omitnan');
                    
                case "average"
                    
                    app.CurrentMap = mean( ...
                        selectedCube, ...
                        3, ...
                        'omitnan');
                    
                otherwise
                    
                    error('Unknown filter calculation method: %s', method);
            end
            % A newly calculated Raman band is always raw.
            app.RawCurrentMap = double(app.CurrentMap);
            app.FilteredCurrentMap = [];
            app.FilterWasApplied = false;
            app.FilterSettings = struct();
            
        end


        function filterTableSelectionChanged(app, event)
            
            if isempty(event.Indices)
                return;
            end
            
            selectedRow = event.Indices(1, 1);
            
            if selectedRow < 1 || ...
                    selectedRow > height(app.BandFilters)
                return;
            end
            
            app.CurrentFilterIndex = selectedRow;
            
            try
                calculateCurrentFilterMap(app);
                updateSelectedFilterInformation(app);
                refreshDisplay(app);
                highlightCurrentFilter(app);
                
            catch ME
                uialert(app.FilterFigure, ...
                    ME.message, ...
                    'Filter error', ...
                    'Icon', 'error');
            end
            
        end


        function updateSelectedFilterInformation(app)
            
            if isempty(app.BandFilters) || ...
                    isnan(app.CurrentFilterIndex)
                
                % app.TextArea.Value = {'No filter selected'};
                return;
            end
            
            filterIndex = app.CurrentFilterIndex;
            
            if filterIndex < 1 || ...
                    filterIndex > height(app.BandFilters)
                
                % app.TextArea.Value = {'No filter selected'};
                return;
            end
            
            filterRow = app.BandFilters(filterIndex, :);
            
            % app.TextArea.Value = {
            %     sprintf('Filter: %s', filterRow.Name)
            %     sprintf('Range: %.2f - %.2f cm^-1', ...
            %     filterRow.LowWN, filterRow.HighWN)
            %     sprintf('Method: %s | Points: %d', ...
            %     filterRow.Method, filterRow.PointCount)
            %     };
            
        end


        function highlightCurrentFilter(app)
            
            if ~isempty(app.CurrentFilterHighlightHandles)
                
                validHandles = ...
                    isgraphics(app.CurrentFilterHighlightHandles);
                
                if any(validHandles)
                    delete(app.CurrentFilterHighlightHandles(validHandles));
                end
            end
            
            app.CurrentFilterHighlightHandles = gobjects(0);
            
            if isempty(app.FilterAxes) || ...
                    ~isvalid(app.FilterAxes) || ...
                    isempty(app.BandFilters) || ...
                    isnan(app.CurrentFilterIndex)
                
                return;
            end
            
            filterIndex = app.CurrentFilterIndex;
            
            if filterIndex < 1 || ...
                    filterIndex > height(app.BandFilters)
                return;
            end
            
            lowWN = app.BandFilters.LowWN(filterIndex);
            highWN = app.BandFilters.HighWN(filterIndex);
            
            hold(app.FilterAxes, 'on');
            
            leftHighlight = xline( ...
                app.FilterAxes, ...
                lowWN, ...
                '-', ...
                'LineWidth', 2.5, ...
                'HitTest', 'off', ...
                'PickableParts', 'none');
            
            rightHighlight = xline( ...
                app.FilterAxes, ...
                highWN, ...
                '-', ...
                'LineWidth', 2.5, ...
                'HitTest', 'off', ...
                'PickableParts', 'none');
            
            hold(app.FilterAxes, 'off');
            
            app.CurrentFilterHighlightHandles = ...
                [leftHighlight rightHighlight];
            
        end


        function filterTableEdited(app, event)
            
            if isempty(event.Indices)
                return;
            end
            
            editedRow = event.Indices(1);
            editedColumn = event.Indices(2);
            
            if editedRow < 1 || ...
                    editedRow > height(app.BandFilters)
                return;
            end
            
            try
                switch editedColumn
                    
                    case 2
                        % Name
                        newName = string(event.NewData);
                        
                        if strlength(strtrim(newName)) == 0
                            newName = "Filter " + string(editedRow);
                        end
                        
                        app.BandFilters.Name(editedRow) = newName;
                        
                    case 3
                        % Low WN
                        newLowWN = double(event.NewData);
                        
                        if ~isfinite(newLowWN)
                            error('Low WN must be numeric.');
                        end
                        
                        app.BandFilters.LowWN(editedRow) = newLowWN;
                        
                    case 4
                        % High WN
                        newHighWN = double(event.NewData);
                        
                        if ~isfinite(newHighWN)
                            error('High WN must be numeric.');
                        end
                        
                        app.BandFilters.HighWN(editedRow) = newHighWN;
                        
                    case 5
                        % Method
                        newMethod = string(event.NewData);
                        
                        if ~any(strcmpi(newMethod, ...
                                ["Sum", "Average"]))
                            
                            error('Method must be Sum or Average.');
                        end
                        
                        if strcmpi(newMethod, "sum")
                            newMethod = "Sum";
                        else
                            newMethod = "Average";
                        end
                        
                        app.BandFilters.Method(editedRow) = newMethod;
                end
                
                lowWN = app.BandFilters.LowWN(editedRow);
                highWN = app.BandFilters.HighWN(editedRow);
                
                if lowWN > highWN
                    temporary = lowWN;
                    lowWN = highWN;
                    highWN = temporary;
                    
                    app.BandFilters.LowWN(editedRow) = lowWN;
                    app.BandFilters.HighWN(editedRow) = highWN;
                end
                
                lowWN = max(lowWN, min(app.Wavenumber));
                highWN = min(highWN, max(app.Wavenumber));
                
                app.BandFilters.LowWN(editedRow) = lowWN;
                app.BandFilters.HighWN(editedRow) = highWN;
                
                indices = find( ...
                    app.Wavenumber >= lowWN & ...
                    app.Wavenumber <= highWN);
                
                if isempty(indices)
                    [~, nearestIndex] = min( ...
                        abs(app.Wavenumber - mean([lowWN highWN])));
                    
                    indices = nearestIndex;
                end
                
                app.BandFilters.LowWN(editedRow) = ...
                    app.Wavenumber(indices(1));
                
                app.BandFilters.HighWN(editedRow) = ...
                    app.Wavenumber(indices(end));
                
                app.BandFilters.PointCount(editedRow) = ...
                    numel(indices);
                
                if app.CurrentFilterIndex == editedRow
                    calculateCurrentFilterMap(app);
                    updateSelectedFilterInformation(app);
                    refreshDisplay(app);
                end
                
                refreshFilterManager(app);
                
            catch ME
                
                refreshFilterManager(app);
                
                uialert(app.FilterFigure, ...
                    ME.message, ...
                    'Invalid filter value', ...
                    'Icon', 'error');
            end
            
        end


        function [displayMap, displayMask] = ...
                prepareBandMapForDisplay(app, inputMap)
            
            % =========================================================
            % DISPLAY-ONLY RAMAN MAP ENHANCEMENT
            %
            % Empty/unacquired scan pixels are excluded from percentile
            % calculation and shown as black.
            %
            % app.CurrentMap and app.Cube are not modified here.
            % =========================================================
            
            map = double(inputMap);
            
            if isempty(map)
                
                displayMap = [];
                displayMask = [];
                return;
            end
            
            % =========================================================
            % 1. BUILD DISPLAY VALIDITY MASK
            % =========================================================
            
            finiteMask = ...
                isfinite(map);
            
            if ~isempty(app.AcquiredMask2D) && ...
                    isequal(size(app.AcquiredMask2D), size(map))
                
                acquiredMask = ...
                    logical(app.AcquiredMask2D);
                
            else
                
                acquiredMask = ...
                    true(size(map));
            end
            
            validDisplayMask = ...
                finiteMask & acquiredMask;
            
            finiteValues = ...
                map(validDisplayMask);
            
            if isempty(finiteValues)
                
                displayMap = ...
                    zeros(size(map));
                
                displayMask = ...
                    false(size(map));
                
                return;
            end
            
            % Temporarily fill invalid locations only for safe arithmetic.
            fillValue = ...
                median(finiteValues, 'omitnan');
            
            map(~validDisplayMask) = ...
                fillValue;
            
            % =========================================================
            % 2. ROBUST DISPLAY LIMITS
            %
            % Percentiles use only actually acquired Raman pixels.
            % =========================================================
            
            lowerPercentile = 8;
            upperPercentile = 99.8;
            
            lowerLimit = ...
                manualPercentile( ...
                app, ...
                finiteValues, ...
                lowerPercentile);
            
            upperLimit = ...
                manualPercentile( ...
                app, ...
                finiteValues, ...
                upperPercentile);
            
            if ~isfinite(lowerLimit)
                
                lowerLimit = ...
                    min(finiteValues);
            end
            
            if ~isfinite(upperLimit)
                
                upperLimit = ...
                    max(finiteValues);
            end
            
            if upperLimit <= lowerLimit
                
                lowerLimit = ...
                    min(finiteValues);
                
                upperLimit = ...
                    max(finiteValues);
                
                if upperLimit <= lowerLimit
                    
                    displayMap = ...
                        zeros(size(map));
                    
                    displayMask = ...
                        validDisplayMask;
                    
                    return;
                end
            end
            
            % =========================================================
            % 3. VISUAL BACKGROUND MASK
            % =========================================================
            
            displayMask = ...
                validDisplayMask & ...
                map > lowerLimit;
            
            % =========================================================
            % 4. CLIP AND NORMALIZE
            % =========================================================
            
            map = ...
                min( ...
                max(map, lowerLimit), ...
                upperLimit);
            
            map = ...
                (map - lowerLimit) ./ ...
                max(upperLimit - lowerLimit, eps);
            
            map = ...
                max(0, min(1, map));
            
            % =========================================================
            % 5. BRIGHTNESS COMPRESSION
            % =========================================================
            
            displayGamma = 1.55;
            
            map = ...
                map .^ displayGamma;
            
            % =========================================================
            % 6. OPTIONAL DISPLAY SMOOTHING
            %
            % These affect only displayMap, not Cube or CurrentMap.
            % =========================================================
            
            % try
            %
            %     map = ...
            %         medfilt2( ...
            %         map, ...
            %         [3 3], ...
            %         'symmetric');
            %
            % catch
            % end
            
            % try
            %
            %     map = ...
            %         imgaussfilt( ...
            %         map, ...
            %         0.40, ...
            %         'Padding', ...
            %         'symmetric');
            %
            % catch
            % end
            
            map = ...
                max(0, min(1, map));
            
            % Unacquired and display-background pixels are exactly black.
            map(~displayMask) = 0;
            
            displayMap = ...
                map;
            
        end


        function deleteSelectedBandFilter(app)
            
            if isempty(app.BandFilters)
                return;
            end
            
            if isnan(app.CurrentFilterIndex)
                uialert(app.FilterFigure, ...
                    'Select a filter from the table first.', ...
                    'No filter selected', ...
                    'Icon', 'warning');
                return;
            end
            
            selectedIndex = app.CurrentFilterIndex;
            
            if selectedIndex < 1 || ...
                    selectedIndex > height(app.BandFilters)
                return;
            end
            
            selectedName = app.BandFilters.Name(selectedIndex);
            
            choice = uiconfirm( ...
                app.FilterFigure, ...
                sprintf('Delete filter "%s"?', selectedName), ...
                'Delete filter', ...
                'Options', {'Delete', 'Cancel'}, ...
                'DefaultOption', 'Cancel', ...
                'CancelOption', 'Cancel');
            
            if strcmp(choice, 'Cancel')
                return;
            end
            
            app.BandFilters(selectedIndex, :) = [];
            
            % Reassign IDs
            if ~isempty(app.BandFilters)
                app.BandFilters.ID = ...
                    (1:height(app.BandFilters))';
            end
            
            if isempty(app.BandFilters)
                
                app.CurrentFilterIndex = NaN;
                app.CurrentBand = [NaN NaN];
                app.CurrentBandIndices = [];
                app.CurrentMap = [];
                
            else
                
                app.CurrentFilterIndex = min( ...
                    selectedIndex, ...
                    height(app.BandFilters));
                
                calculateCurrentFilterMap(app);
            end
            
            updateSelectedFilterInformation(app);
            refreshFilterManager(app);
            refreshDisplay(app);
            
        end


        function synchronizeTraceToCubeGrid(app)
            
            if isempty(app.Cube)
                app.TraceImage = [];
                return;
            end
            
            if isempty(app.TraceImageOriginal)
                error('Trace image is mandatory.');
            end
            
            targetRows = size(app.Cube, 1);
            targetColumns = size(app.Cube, 2);
            
            originalTrace = app.TraceImageOriginal;
            
            try
                app.TraceImage = imresize( ...
                    originalTrace, ...
                    [targetRows targetColumns], ...
                    'bicubic', ...
                    'Antialiasing', true);
            catch
                app.TraceImage = imresize( ...
                    originalTrace, ...
                    [targetRows targetColumns], ...
                    'bicubic');
            end
        end


        function updateAcquiredMask(app)
            
            % =========================================================
            % IDENTIFY ACTUALLY ACQUIRED RAMAN PIXELS
            %
            % An interrupted scan commonly leaves complete spectra filled
            % with zeros. These pixels must not affect display percentiles.
            %
            % This mask is not a biological cell/background mask.
            % It only identifies whether Raman data were acquired.
            % =========================================================
            
            if isempty(app.Cube)
                
                app.AcquiredMask2D = logical.empty;
                return;
            end
            
            cube = double(app.Cube);
            
            finiteCube = cube;
            finiteCube(~isfinite(finiteCube)) = 0;
            
            % Maximum absolute signal in each pixel spectrum.
            spectralMagnitude = ...
                max(abs(finiteCube), [], 3);
            
            globalMagnitude = ...
                max(spectralMagnitude(:));
            
            if ~isfinite(globalMagnitude) || ...
                    globalMagnitude <= eps
                
                app.AcquiredMask2D = ...
                    false(size(spectralMagnitude));
                
                return;
            end
            
            % Extremely small tolerance: removes truly empty spectra without
            % rejecting legitimate low-intensity Raman spectra.
            emptyTolerance = ...
                max( ...
                1e-12 * globalMagnitude, ...
                100 * eps(globalMagnitude));
            
            acquiredMask = ...
                spectralMagnitude > emptyTolerance;
            
            % A spectrum must also contain finite values.
            finiteSpectrumMask = ...
                any(isfinite(cube), 3);
            
            app.AcquiredMask2D = ...
                acquiredMask & finiteSpectrumMask;
            
        end


        function files = locateScanFiles(app, folderPath)
            
            folderPath = char(string(folderPath));
            
            if ~isfolder(folderPath)
                error('The selected scan folder does not exist:\n%s', ...
                    folderPath);
            end
            
            allFiles = dir(folderPath);
            allFiles = allFiles(~[allFiles.isdir]);
            
            files = struct();
            files.Header = '';
            files.XAxis = '';
            files.YAxis = '';
            files.Trace = '';
            
            imageExtensions = ...
                {'.png', '.jpg', '.jpeg', '.tif', '.tiff', '.bmp'};
            
            traceCandidates = {};
            
            for k = 1:numel(allFiles)
                
                fileName = allFiles(k).name;
                fullName = fullfile(folderPath, fileName);
                lowerName = lower(fileName);
                
                [~, ~, extension] = fileparts(fileName);
                extension = lower(extension);
                
                if contains(lowerName, 'header') && ...
                        strcmp(extension, '.txt')
                    
                    files.Header = fullName;
                    
                elseif contains(lowerName, 'x-axis') && ...
                        strcmp(extension, '.txt')
                    
                    files.XAxis = fullName;
                    
                elseif contains(lowerName, 'y-axis') && ...
                        strcmp(extension, '.txt')
                    
                    files.YAxis = fullName;
                    
                elseif any(strcmp(extension, imageExtensions))
                    
                    traceCandidates{end + 1} = fullName; %#ok<AGROW>
                    
                    % Prefer an image explicitly containing "trace".
                    if contains(lowerName, 'trace')
                        files.Trace = fullName;
                    end
                end
            end
            
            if isempty(files.Header)
                error('Header TXT file was not found.');
            end
            
            if isempty(files.XAxis)
                error('X-Axis TXT file was not found.');
            end
            
            if isempty(files.YAxis)
                error('Y-Axis TXT file was not found.');
            end
            
            % If only one image exists, accept it as trace.
            if isempty(files.Trace) && numel(traceCandidates) == 1
                files.Trace = traceCandidates{1};
            end
            
            % Trace is mandatory.
            if isempty(files.Trace)
                error([ ...
                    'A trace image is mandatory, but no valid trace image ' ...
                    'was found in:\n\n%s\n\n' ...
                    'Use PNG, JPG, JPEG, TIF, TIFF or BMP. ' ...
                    'Preferably include "trace" in the filename.'], ...
                    folderPath);
            end
            
            if ~isfile(files.Trace)
                error('The detected trace image does not exist:\n%s', ...
                    files.Trace);
            end
        end


        function autoOrientCubeToTrace(app)
            
            if isempty(app.Cube)
                error('Raman cube is empty.');
            end
            
            if isempty(app.TraceImageOriginal)
                error('Trace image is required for automatic registration.');
            end
            
            referenceTrace = ...
                prepareRegistrationImage(app, app.TraceImageOriginal);
            
            registrationMap = ...
                buildRegistrationBandMap(app, app.Cube);
            
            bestScore = -Inf;
            bestCube = app.Cube;
            bestMask = app.AcquiredMask2D;
            
            bestRotation = 0;
            bestFlipHorizontal = false;
            bestDescription = 'No orientation change';
            
            for rotationIndex = 0:3
                
                rotatedCube = rot90(app.Cube, rotationIndex);
                
                if ~isempty(app.AcquiredMask2D)
                    rotatedMask = ...
                        rot90(app.AcquiredMask2D, rotationIndex);
                else
                    rotatedMask = [];
                end
                
                rotatedMap = rot90(registrationMap, rotationIndex);
                
                for flipIndex = 0:1
                    
                    candidateCube = rotatedCube;
                    candidateMask = rotatedMask;
                    candidateMap = rotatedMap;
                    
                    if flipIndex == 1
                        candidateCube = flip(candidateCube, 2);
                        candidateMap = flip(candidateMap, 2);
                        
                        if ~isempty(candidateMask)
                            candidateMask = flip(candidateMask, 2);
                        end
                    end
                    
                    candidateDisplay = imresize( ...
                        candidateMap, ...
                        size(referenceTrace), ...
                        'bicubic');
                    
                    candidateDisplay = ...
                        prepareRegistrationImage(app, candidateDisplay);
                    
                    currentScore = ...
                        calculateRegistrationScore( ...
                        app, ...
                        referenceTrace, ...
                        candidateDisplay);
                    
                    if currentScore > bestScore
                        
                        bestScore = currentScore;
                        bestCube = candidateCube;
                        bestMask = candidateMask;
                        
                        bestRotation = rotationIndex;
                        bestFlipHorizontal = logical(flipIndex);
                        
                        bestDescription = sprintf( ...
                            'Automatic: rotation %d degrees, horizontal flip %s', ...
                            rotationIndex * 90, ...
                            string(logical(flipIndex)));
                    end
                end
            end
            
            app.Cube = bestCube;
            
            if ~isempty(bestMask)
                app.AcquiredMask2D = logical(bestMask);
            end
            
            app.RotationQuarterTurns = mod(bestRotation, 4);
            app.FlipHorizontal = bestFlipHorizontal;
            app.FlipVertical = false;
            
            app.AutoOrientationScore = bestScore;
            app.AutoOrientationMethod = bestDescription;
            app.AutoOrientationWasApplied = true;
            
            app.OrientationHistory{end + 1} = bestDescription;
        end
        
        
        function map = buildRegistrationBandMap(app, cube)
            
            cube = double(cube);
            
            if isempty(cube)
                map = [];
                return;
            end
            
            wavenumber = double(app.Wavenumber(:));
            
            % Prefer the fingerprint region because it normally contains
            % stronger biological spatial structure.
            selectedIndices = find( ...
                wavenumber >= 700 & ...
                wavenumber <= 1800);
            
            if numel(selectedIndices) < 3
                
                selectedIndices = find( ...
                    wavenumber >= 2800 & ...
                    wavenumber <= 3050);
            end
            
            if numel(selectedIndices) < 3
                selectedIndices = 1:size(cube, 3);
            end
            
            selectedCube = cube(:, :, selectedIndices);
            
            % Robust integrated absolute deviation from each pixel baseline.
            pixelBaseline = median(selectedCube, 3, 'omitnan');
            
            residualCube = ...
                abs(selectedCube - pixelBaseline);
            
            map = mean(residualCube, 3, 'omitnan');
            
            map(~isfinite(map)) = 0;
            
            if ~isempty(app.AcquiredMask2D) && ...
                    isequal(size(app.AcquiredMask2D), size(map))
                
                map(~app.AcquiredMask2D) = 0;
            end
            
            map = normalizeImageRobustly(app, map);
        end
        
        
        function outputImage = prepareRegistrationImage(app, inputImage)
            
            imageData = double(inputImage);
            
            if ndims(imageData) == 3
                
                if size(imageData, 3) >= 3
                    imageData = ...
                        0.2989 * imageData(:, :, 1) + ...
                        0.5870 * imageData(:, :, 2) + ...
                        0.1140 * imageData(:, :, 3);
                else
                    imageData = imageData(:, :, 1);
                end
            end
            
            imageData(~isfinite(imageData)) = 0;
            
            imageData = normalizeImageRobustly(app, imageData);
            
            try
                imageData = imgaussfilt( ...
                    imageData, ...
                    1.0, ...
                    'Padding', 'symmetric');
            catch
            end
            
            % Registration is based on spatial edges rather than absolute
            % brightness, reducing sensitivity to trace illumination.
            try
                gradientX = imfilter( ...
                    imageData, ...
                    [1 0 -1], ...
                    'replicate');
                
                gradientY = imfilter( ...
                    imageData, ...
                    [1; 0; -1], ...
                    'replicate');
                
                imageData = hypot(gradientX, gradientY);
            catch
            end
            
            outputImage = normalizeImageRobustly(app, imageData);
        end
        
        
        function normalizedImage = normalizeImageRobustly(app, inputImage)
            
            inputImage = double(inputImage);
            
            finiteValues = inputImage(isfinite(inputImage));
            
            if isempty(finiteValues)
                normalizedImage = zeros(size(inputImage));
                return;
            end
            
            lowValue = manualPercentile(app, finiteValues, 2);
            highValue = manualPercentile(app, finiteValues, 98);
            
            if ~isfinite(lowValue)
                lowValue = min(finiteValues);
            end
            
            if ~isfinite(highValue)
                highValue = max(finiteValues);
            end
            
            if highValue <= lowValue
                normalizedImage = zeros(size(inputImage));
                return;
            end
            
            inputImage = min(max(inputImage, lowValue), highValue);
            
            normalizedImage = ...
                (inputImage - lowValue) ./ ...
                max(highValue - lowValue, eps);
            
            normalizedImage = max(0, min(1, normalizedImage));
        end
        
        
        function score = calculateRegistrationScore(app, reference, candidate)
            
            reference = double(reference);
            candidate = double(candidate);
            
            if ~isequal(size(reference), size(candidate))
                candidate = imresize( ...
                    candidate, ...
                    size(reference), ...
                    'bicubic');
            end
            
            validMask = ...
                isfinite(reference) & ...
                isfinite(candidate);
            
            referenceValues = reference(validMask);
            candidateValues = candidate(validMask);
            
            if numel(referenceValues) < 20
                score = -Inf;
                return;
            end
            
            referenceValues = referenceValues - mean(referenceValues);
            candidateValues = candidateValues - mean(candidateValues);
            
            denominator = ...
                sqrt(sum(referenceValues .^ 2)) * ...
                sqrt(sum(candidateValues .^ 2));
            
            if denominator <= eps
                score = -Inf;
                return;
            end
            
            positiveCorrelation = ...
                sum(referenceValues .* candidateValues) / denominator;
            
            % Trace contrast may occasionally be inverted.
            negativeCorrelation = ...
                sum(referenceValues .* (-candidateValues)) / denominator;
            
            score = max(positiveCorrelation, negativeCorrelation);
        end

        function openImageFilterPreview(app)
            
            if ~isempty(app.ImageFilterFigure) && ...
                    isvalid(app.ImageFilterFigure)
                
                figure(app.ImageFilterFigure);
                updateImageFilterPreview(app);
                return;
            end
            
            app.ImageFilterPreviewRaw = double(app.CurrentMap);
            
            app.ImageFilterFigure = uifigure( ...
                'Name', 'Raman map filter preview', ...
                'Position', [70 80 1380 760], ...
                'CloseRequestFcn', ...
                @(src,event) closeImageFilterPreview(app));
            
            mainGrid = uigridlayout( ...
                app.ImageFilterFigure, ...
                [3 3]);
            
            mainGrid.RowHeight = {'1x', 130, 58};
            mainGrid.ColumnWidth = {'1x', '1x', '1x'};
            mainGrid.Padding = [12 12 12 12];
            mainGrid.RowSpacing = 10;
            mainGrid.ColumnSpacing = 10;
            
            % =========================================================
            % THREE IMAGE AXES
            % =========================================================
            
            app.ImageFilterRawAxes = uiaxes(mainGrid);
            app.ImageFilterRawAxes.Layout.Row = 1;
            app.ImageFilterRawAxes.Layout.Column = 1;
            app.ImageFilterRawAxes.Box = 'on';
            title(app.ImageFilterRawAxes, 'Raw Raman map');
            
            app.ImageFilterResultAxes = uiaxes(mainGrid);
            app.ImageFilterResultAxes.Layout.Row = 1;
            app.ImageFilterResultAxes.Layout.Column = 2;
            app.ImageFilterResultAxes.Box = 'on';
            title(app.ImageFilterResultAxes, 'Filtered preview');
            
            app.ImageFilterCorrelationAxes = uiaxes(mainGrid);
            app.ImageFilterCorrelationAxes.Layout.Row = 1;
            app.ImageFilterCorrelationAxes.Layout.Column = 3;
            app.ImageFilterCorrelationAxes.Box = 'on';
            title(app.ImageFilterCorrelationAxes, ...
                'Local raw-filtered correlation');
            
            % =========================================================
            % CONTROLS
            % =========================================================
            
            controlPanel = uipanel(mainGrid);
            controlPanel.Layout.Row = 2;
            controlPanel.Layout.Column = [1 3];
            controlPanel.Title = 'Live filter parameters';
            
            controlGrid = uigridlayout(controlPanel, [2 10]);
            
            controlGrid.RowHeight = {38, 38};
            controlGrid.ColumnWidth = ...
                {100, 90, 110, 90, 100, 90, 110, 90, 110, '1x'};
            
            % Median
            medianLabel = uilabel(controlGrid);
            medianLabel.Text = 'Median size';
            medianLabel.HorizontalAlignment = 'right';
            medianLabel.Layout.Row = 1;
            medianLabel.Layout.Column = 1;
            
            app.ImageFilterMedianSpinner = ...
                uispinner(controlGrid);
            
            app.ImageFilterMedianSpinner.Limits = [1 15];
            app.ImageFilterMedianSpinner.Step = 2;
            app.ImageFilterMedianSpinner.Value = 3;
            app.ImageFilterMedianSpinner.RoundFractionalValues = 'on';
            app.ImageFilterMedianSpinner.Layout.Row = 1;
            app.ImageFilterMedianSpinner.Layout.Column = 2;
            app.ImageFilterMedianSpinner.ValueChangedFcn = ...
                @(src,event) updateImageFilterPreview(app);
            
            % Gaussian
            gaussianLabel = uilabel(controlGrid);
            gaussianLabel.Text = 'Gaussian sigma';
            gaussianLabel.HorizontalAlignment = 'right';
            gaussianLabel.Layout.Row = 1;
            gaussianLabel.Layout.Column = 3;
            
            app.ImageFilterGaussianSpinner = ...
                uispinner(controlGrid);
            
            app.ImageFilterGaussianSpinner.Limits = [0 3];
            app.ImageFilterGaussianSpinner.Step = 0.05;
            app.ImageFilterGaussianSpinner.Value = 0.35;
            app.ImageFilterGaussianSpinner.Layout.Row = 1;
            app.ImageFilterGaussianSpinner.Layout.Column = 4;
            app.ImageFilterGaussianSpinner.ValueChangedFcn = ...
                @(src,event) updateImageFilterPreview(app);
            
            % Blend
            blendLabel = uilabel(controlGrid);
            blendLabel.Text = 'Filter strength';
            blendLabel.HorizontalAlignment = 'right';
            blendLabel.Layout.Row = 1;
            blendLabel.Layout.Column = 5;
            
            app.ImageFilterBlendSpinner = ...
                uispinner(controlGrid);
            
            app.ImageFilterBlendSpinner.Limits = [0 1];
            app.ImageFilterBlendSpinner.Step = 0.05;
            app.ImageFilterBlendSpinner.Value = 1;
            app.ImageFilterBlendSpinner.Layout.Row = 1;
            app.ImageFilterBlendSpinner.Layout.Column = 6;
            app.ImageFilterBlendSpinner.ValueChangedFcn = ...
                @(src,event) updateImageFilterPreview(app);
            
            % Lower percentile
            lowerLabel = uilabel(controlGrid);
            lowerLabel.Text = 'Display low %';
            lowerLabel.HorizontalAlignment = 'right';
            lowerLabel.Layout.Row = 1;
            lowerLabel.Layout.Column = 7;
            
            app.ImageFilterLowerSpinner = ...
                uispinner(controlGrid);
            
            app.ImageFilterLowerSpinner.Limits = [0 40];
            app.ImageFilterLowerSpinner.Step = 0.5;
            app.ImageFilterLowerSpinner.Value = 2;
            app.ImageFilterLowerSpinner.Layout.Row = 1;
            app.ImageFilterLowerSpinner.Layout.Column = 8;
            app.ImageFilterLowerSpinner.ValueChangedFcn = ...
                @(src,event) updateImageFilterPreview(app);
            
            % Upper percentile
            upperLabel = uilabel(controlGrid);
            upperLabel.Text = 'Display high %';
            upperLabel.HorizontalAlignment = 'right';
            upperLabel.Layout.Row = 1;
            upperLabel.Layout.Column = 9;
            
            app.ImageFilterUpperSpinner = ...
                uispinner(controlGrid);
            
            app.ImageFilterUpperSpinner.Limits = [60 100];
            app.ImageFilterUpperSpinner.Step = 0.1;
            app.ImageFilterUpperSpinner.Value = 99.5;
            app.ImageFilterUpperSpinner.Layout.Row = 1;
            app.ImageFilterUpperSpinner.Layout.Column = 10;
            app.ImageFilterUpperSpinner.ValueChangedFcn = ...
                @(src,event) updateImageFilterPreview(app);
            
            app.ImageFilterStatusLabel = uilabel(controlGrid);
            app.ImageFilterStatusLabel.Layout.Row = 2;
            app.ImageFilterStatusLabel.Layout.Column = [1 10];
            app.ImageFilterStatusLabel.Text = ...
                'Adjust any parameter to update all three images.';
            app.ImageFilterStatusLabel.HorizontalAlignment = 'center';
            
            % =========================================================
            % BOTTOM BUTTONS
            % =========================================================
            
            bottomGrid = uigridlayout(mainGrid, [1 4]);
            
            bottomGrid.Layout.Row = 3;
            bottomGrid.Layout.Column = [1 3];
            
            bottomGrid.ColumnWidth = {'1x', 140, 140, 140};
            
            resetButton = uibutton(bottomGrid, 'push');
            resetButton.Text = 'Reset defaults';
            resetButton.Layout.Column = 2;
            resetButton.ButtonPushedFcn = ...
                @(src,event) resetImageFilterPreview(app);
            
            finishButton = uibutton(bottomGrid, 'push');
            finishButton.Text = 'Finish';
            finishButton.Layout.Column = 3;
            finishButton.ButtonPushedFcn = ...
                @(src,event) finishImageFilterPreview(app);
            
            cancelButton = uibutton(bottomGrid, 'push');
            cancelButton.Text = 'Cancel';
            cancelButton.Layout.Column = 4;
            cancelButton.ButtonPushedFcn = ...
                @(src,event) closeImageFilterPreview(app);
            
            updateImageFilterPreview(app);
        end
        
        
        function updateImageFilterPreview(app)
            
            if isempty(app.ImageFilterFigure) || ...
                    ~isvalid(app.ImageFilterFigure)
                return;
            end
            
            rawMap = double(app.ImageFilterPreviewRaw);
            
            if isempty(rawMap)
                return;
            end
            
            medianSize = round(app.ImageFilterMedianSpinner.Value);
            
            % Force a positive odd kernel.
            medianSize = max(1, medianSize);
            
            if mod(medianSize, 2) == 0
                medianSize = medianSize + 1;
            end
            
            medianSize = min(medianSize, 15);
            
            app.ImageFilterMedianSpinner.Value = medianSize;
            
            gaussianSigma = ...
                double(app.ImageFilterGaussianSpinner.Value);
            
            blendStrength = ...
                double(app.ImageFilterBlendSpinner.Value);
            
            lowerPercentile = ...
                double(app.ImageFilterLowerSpinner.Value);
            
            upperPercentile = ...
                double(app.ImageFilterUpperSpinner.Value);
            
            if upperPercentile <= lowerPercentile
                upperPercentile = min(100, lowerPercentile + 1);
                app.ImageFilterUpperSpinner.Value = upperPercentile;
            end
            
            validMask = isfinite(rawMap);
            
            if ~isempty(app.AcquiredMask2D) && ...
                    isequal(size(app.AcquiredMask2D), size(rawMap))
                
                validMask = ...
                    validMask & logical(app.AcquiredMask2D);
            end
            
            validValues = rawMap(validMask);
            
            if isempty(validValues)
                return;
            end
            
            fillValue = median(validValues, 'omitnan');
            
            workingMap = rawMap;
            workingMap(~validMask) = fillValue;
            
            % =========================================================
            % EDGE-PRESERVING CLEANING
            % =========================================================
            
            if medianSize > 1
                
                try
                    medianResult = medfilt2( ...
                        workingMap, ...
                        [medianSize medianSize], ...
                        'symmetric');
                catch
                    medianResult = workingMap;
                end
            else
                medianResult = workingMap;
            end
            
            if gaussianSigma > 0
                
                try
                    filteredMap = imgaussfilt( ...
                        medianResult, ...
                        gaussianSigma, ...
                        'Padding', 'symmetric');
                catch
                    filteredMap = medianResult;
                end
            else
                filteredMap = medianResult;
            end
            
            resultMap = ...
                (1 - blendStrength) .* workingMap + ...
                blendStrength .* filteredMap;
            
            resultMap(~validMask) = NaN;
            
            correlationMap = ...
                calculateLocalCorrelationMap( ...
                app, ...
                workingMap, ...
                resultMap, ...
                validMask, ...
                max(3, medianSize));
            
            app.ImageFilterPreviewResult = resultMap;
            app.ImageFilterPreviewCorrelation = correlationMap;
            
            % =========================================================
            % DISPLAY
            % =========================================================
            
            showFilterPreviewImage( ...
                app, ...
                app.ImageFilterRawAxes, ...
                rawMap, ...
                validMask, ...
                lowerPercentile, ...
                upperPercentile, ...
                'Raw Raman map');
            
            showFilterPreviewImage( ...
                app, ...
                app.ImageFilterResultAxes, ...
                resultMap, ...
                validMask, ...
                lowerPercentile, ...
                upperPercentile, ...
                sprintf( ...
                'Filtered | median %d | sigma %.2f | strength %.2f', ...
                medianSize, ...
                gaussianSigma, ...
                blendStrength));
            
            cla(app.ImageFilterCorrelationAxes);
            
            correlationDisplay = correlationMap;
            correlationDisplay(~validMask) = NaN;
            
            correlationImage = imagesc( ...
                app.ImageFilterCorrelationAxes, ...
                correlationDisplay, ...
                [-1 1]);
            
            correlationImage.AlphaData = double(validMask);
            
            axis(app.ImageFilterCorrelationAxes, 'image');
            app.ImageFilterCorrelationAxes.YDir = 'reverse';
            app.ImageFilterCorrelationAxes.XTick = [];
            app.ImageFilterCorrelationAxes.YTick = [];
            app.ImageFilterCorrelationAxes.Color = [0 0 0];
            app.ImageFilterCorrelationAxes.Box = 'on';
            
            colormap(app.ImageFilterCorrelationAxes, turbo(256));
            colorbar(app.ImageFilterCorrelationAxes);
            
            title( ...
                app.ImageFilterCorrelationAxes, ...
                'Local correlation: 1 = unchanged');
            
            validCorrelation = correlationMap(validMask);
            validCorrelation = ...
                validCorrelation(isfinite(validCorrelation));
            
            if isempty(validCorrelation)
                medianCorrelation = NaN;
                lowCorrelationFraction = NaN;
            else
                medianCorrelation = ...
                    median(validCorrelation, 'omitnan');
                
                lowCorrelationFraction = ...
                    mean(validCorrelation < 0.90, 'omitnan');
            end
            
            app.ImageFilterStatusLabel.Text = sprintf([ ...
                'Median local correlation: %.4f | ' ...
                'Pixels below 0.90: %.2f %%'], ...
                medianCorrelation, ...
                100 * lowCorrelationFraction);
            
            drawnow limitrate;
        end
        
        
        function correlationMap = calculateLocalCorrelationMap( ...
                app, rawMap, filteredMap, validMask, windowSize)
            
            rawMap = double(rawMap);
            filteredMap = double(filteredMap);
            
            windowSize = max(3, round(windowSize));
            
            if mod(windowSize, 2) == 0
                windowSize = windowSize + 1;
            end
            
            kernel = ...
                ones(windowSize, windowSize) ./ ...
                (windowSize * windowSize);
            
            rawMap(~validMask) = 0;
            filteredMap(~validMask) = 0;
            
            weight = double(validMask);
            
            localWeight = imfilter( ...
                weight, ...
                kernel, ...
                'symmetric');
            
            localWeight = max(localWeight, eps);
            
            meanRaw = ...
                imfilter(rawMap .* weight, kernel, 'symmetric') ./ ...
                localWeight;
            
            meanFiltered = ...
                imfilter(filteredMap .* weight, kernel, 'symmetric') ./ ...
                localWeight;
            
            meanRawSquared = ...
                imfilter( ...
                rawMap .^ 2 .* weight, ...
                kernel, ...
                'symmetric') ./ ...
                localWeight;
            
            meanFilteredSquared = ...
                imfilter( ...
                filteredMap .^ 2 .* weight, ...
                kernel, ...
                'symmetric') ./ ...
                localWeight;
            
            meanCross = ...
                imfilter( ...
                rawMap .* filteredMap .* weight, ...
                kernel, ...
                'symmetric') ./ ...
                localWeight;
            
            varianceRaw = max( ...
                meanRawSquared - meanRaw .^ 2, ...
                0);
            
            varianceFiltered = max( ...
                meanFilteredSquared - meanFiltered .^ 2, ...
                0);
            
            covariance = ...
                meanCross - meanRaw .* meanFiltered;
            
            denominator = ...
                sqrt(varianceRaw .* varianceFiltered);
            
            correlationMap = ...
                covariance ./ max(denominator, eps);
            
            flatMask = ...
                denominator <= ...
                100 * eps(max(denominator(:), [], 'omitnan'));
            
            % If both local regions are flat and equal, regard them as
            % unchanged rather than undefined.
            sameFlatMask = ...
                flatMask & ...
                abs(meanRaw - meanFiltered) <= ...
                100 * eps(max(abs(meanRaw(:)), [], 'omitnan'));
            
            correlationMap(sameFlatMask) = 1;
            correlationMap(flatMask & ~sameFlatMask) = 0;
            
            correlationMap = ...
                max(-1, min(1, correlationMap));
            
            correlationMap(~validMask) = NaN;
        end
        
        
        function showFilterPreviewImage( ...
                app, targetAxes, imageData, validMask, ...
                lowerPercentile, upperPercentile, titleText)
            
            cla(targetAxes);
            
            imageData = double(imageData);
            
            displayValues = imageData(validMask);
            displayValues = displayValues(isfinite(displayValues));
            
            if isempty(displayValues)
                
                imagesc(targetAxes, zeros(size(imageData)), [0 1]);
                title(targetAxes, titleText);
                return;
            end
            
            lowValue = manualPercentile( ...
                app, ...
                displayValues, ...
                lowerPercentile);
            
            highValue = manualPercentile( ...
                app, ...
                displayValues, ...
                upperPercentile);
            
            if ~isfinite(lowValue)
                lowValue = min(displayValues);
            end
            
            if ~isfinite(highValue)
                highValue = max(displayValues);
            end
            
            if highValue <= lowValue
                highValue = lowValue + eps(max(abs(lowValue), 1));
            end
            
            normalizedImage = ...
                (imageData - lowValue) ./ ...
                max(highValue - lowValue, eps);
            
            normalizedImage = ...
                max(0, min(1, normalizedImage));
            
            normalizedImage(~validMask) = 0;
            
            imageHandle = imagesc( ...
                targetAxes, ...
                normalizedImage, ...
                [0 1]);
            
            imageHandle.AlphaData = double(validMask);
            
            axis(targetAxes, 'image');
            
            targetAxes.YDir = 'reverse';
            targetAxes.XTick = [];
            targetAxes.YTick = [];
            targetAxes.Color = [0 0 0];
            targetAxes.Box = 'on';
            
            applyCurrentColormap(app, targetAxes);
            
            title(targetAxes, titleText);
        end
        
        
        function resetImageFilterPreview(app)
            
            if isempty(app.ImageFilterFigure) || ...
                    ~isvalid(app.ImageFilterFigure)
                return;
            end
            
            app.ImageFilterMedianSpinner.Value = 3;
            app.ImageFilterGaussianSpinner.Value = 0.35;
            app.ImageFilterBlendSpinner.Value = 1.0;
            app.ImageFilterLowerSpinner.Value = 2.0;
            app.ImageFilterUpperSpinner.Value = 99.5;
            
            updateImageFilterPreview(app);
        end
        
        
        function finishImageFilterPreview(app)
            
            if isempty(app.ImageFilterPreviewResult)
                return;
            end
            
            choice = uiconfirm( ...
                app.ImageFilterFigure, ...
                { ...
                'Apply the filtered Raman map?'; ...
                ''; ...
                'Apply filter:'; ...
                'Use the preview as the current map.'; ...
                ''; ...
                'Keep raw:'; ...
                'Discard the preview and retain the original map.'}, ...
                'Finish image filter', ...
                'Options', ...
                {'Apply filter', 'Keep raw', 'Cancel'}, ...
                'DefaultOption', ...
                'Apply filter', ...
                'CancelOption', ...
                'Cancel');
            
            switch choice
                
                case 'Apply filter'
                    
                    app.RawCurrentMap = ...
                        double(app.ImageFilterPreviewRaw);
                    
                    app.FilteredCurrentMap = ...
                        double(app.ImageFilterPreviewResult);
                    
                    app.CurrentMap = ...
                        app.FilteredCurrentMap;
                    
                    app.FilterWasApplied = true;
                    
                    app.FilterSettings = struct();
                    
                    app.FilterSettings.Method = ...
                        'Median plus light Gaussian';
                    
                    app.FilterSettings.MedianWindow = ...
                        round(app.ImageFilterMedianSpinner.Value);
                    
                    app.FilterSettings.GaussianSigma = ...
                        double(app.ImageFilterGaussianSpinner.Value);
                    
                    app.FilterSettings.BlendStrength = ...
                        double(app.ImageFilterBlendSpinner.Value);
                    
                    app.FilterSettings.DisplayLowerPercentile = ...
                        double(app.ImageFilterLowerSpinner.Value);
                    
                    app.FilterSettings.DisplayUpperPercentile = ...
                        double(app.ImageFilterUpperSpinner.Value);
                    
                    app.FilterSettings.AppliedAt = ...
                        datetime('now');
                    
                    app.FilterSettings.ModifiesCube = false;
                    app.FilterSettings.ModifiesCurrentMap = true;
                    
                    closeImageFilterPreview(app);
                    refreshDisplay(app);
                    
                    uialert( ...
                        app.UIFigure, ...
                        ['The filtered map is now active. ' ...
                        'The Raman cube was not modified.'], ...
                        'Filter applied', ...
                        'Icon', 'success');
                    
                case 'Keep raw'
                    
                    app.CurrentMap = ...
                        double(app.ImageFilterPreviewRaw);
                    
                    app.RawCurrentMap = ...
                        double(app.ImageFilterPreviewRaw);
                    
                    app.FilteredCurrentMap = [];
                    app.FilterWasApplied = false;
                    app.FilterSettings = struct();
                    
                    closeImageFilterPreview(app);
                    refreshDisplay(app);
                    
                otherwise
                    return;
            end
        end
        
        
        function closeImageFilterPreview(app)
            
            if ~isempty(app.ImageFilterFigure) && ...
                    isvalid(app.ImageFilterFigure)
                
                delete(app.ImageFilterFigure);
            end
            
            app.ImageFilterFigure = [];
            
            app.ImageFilterRawAxes = [];
            app.ImageFilterResultAxes = [];
            app.ImageFilterCorrelationAxes = [];
            
            app.ImageFilterMedianSpinner = [];
            app.ImageFilterGaussianSpinner = [];
            app.ImageFilterBlendSpinner = [];
            app.ImageFilterLowerSpinner = [];
            app.ImageFilterUpperSpinner = [];
            app.ImageFilterStatusLabel = [];
            
            app.ImageFilterPreviewRaw = [];
            app.ImageFilterPreviewResult = [];
            app.ImageFilterPreviewCorrelation = [];
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.WorkingmodesDropDown.Items = ...
                {'Trace - Band', 'Band - Spectra', 'Trace - Spectra'};
            
            app.WorkingmodesDropDown.Value = 'Trace - Band';
            
            app.MapcolorsDropDown.Items = ...
                {'Turbo', 'Jet', 'Hot', 'Gray', 'Parula', 'Bone'};
            
            app.MapcolorsDropDown.Value = 'Hot';
            
            app.CurrentMode = 'Trace - Band';
            app.CurrentColormap = 'hot';
            
            % This callback must actually be assigned during startup
            app.UIFigure.WindowButtonMotionFcn = ...
                @(src,event) figureMouseMoved(app);
            
            title(app.UIAxes, 'Trace');
            title(app.UIAxes2, 'Raman band');
            
            
            app.GeneratescandataButton.Enable = 'off';
            app.BandplotButton.Enable = 'off';
            
            app.BandplotButton.Text = 'Band filters';
            app.ApplyfilterButton.Enable = 'off';
        end

        % Button pushed function: LoadscanButton
        function LoadscanButtonPushed(app, event)
            % =========================================================
            % 1. RESOLVE FIXED RAW INPUT ROOT
            % =========================================================
            
            rawRoot = char(app.RawScanRoot);
            
            if ~isfolder(rawRoot)
                try
                    mkdir(rawRoot);
                catch ME
                    uialert( ...
                        app.UIFigure, ...
                        sprintf([ ...
                        'Could not create the RAW input folder:\n\n%s\n\n%s'], ...
                        rawRoot, ...
                        ME.message), ...
                        'RAW folder error', ...
                        'Icon', 'error');
                    return;
                end
            end
            
            selectedFolder = uigetdir( ...
                rawRoot, ...
                ['Select a RAW scan folder containing Header, ' ...
                'X-Axis, Y-Axis and trace image']);
            
            if isequal(selectedFolder, 0)
                return;
            end
            
            loadingDialog = uiprogressdlg( ...
                app.UIFigure, ...
                'Title', 'Loading Raman scan', ...
                'Message', 'Searching input files...', ...
                'Indeterminate', 'on');
            
            try
                app.SourceFolder = string(selectedFolder);
                
                % =====================================================
                % 2. LOCATE REQUIRED FILES
                % =====================================================
                
                files = locateScanFiles(app, selectedFolder);
                
                app.HeaderFile = string(files.Header);
                app.XAxisFile = string(files.XAxis);
                app.YAxisFile = string(files.YAxis);
                app.TraceFile = string(files.Trace);
                
                loadingDialog.Message = 'Reading Header file...';
                
                app.HeaderText = string(fileread(files.Header));
                app.HeaderInfo = parseHeaderFile(app, files.Header);
                
                loadingDialog.Message = 'Reading X-axis...';
                
                app.Wavenumber = readNumericColumn(app, files.XAxis);
                app.Wavenumber = app.Wavenumber(:);
                
                loadingDialog.Message = ...
                    'Reading Y-axis. Large scans may take some time...';
                
                app.IntensityVector = readNumericColumn(app, files.YAxis);
                app.IntensityVector = app.IntensityVector(:);
                
                loadingDialog.Message = 'Validating scan dimensions...';
                
                validateImportedData(app);
                
                loadingDialog.Message = 'Reconstructing Raman cube...';
                
                reconstructCube(app);
                updateAcquiredMask(app);
                
                loadingDialog.Message = 'Reading mandatory trace image...';
                
                app.TraceImageOriginal = imread(char(app.TraceFile));
                
                if isempty(app.TraceImageOriginal)
                    error('The trace image could not be read.');
                end
                
                % =====================================================
                % 3. RESET SESSION STATE BEFORE AUTO-REGISTRATION
                % =====================================================
                
                app.RotationQuarterTurns = 0;
                app.FlipVertical = false;
                app.FlipHorizontal = false;
                app.OrientationHistory = {};
                
                app.CropHistory = {};
                app.IsSpatiallyCropped = false;
                
                app.AutoOrientationScore = NaN;
                app.AutoOrientationMethod = '';
                app.AutoOrientationWasApplied = false;
                
                app.CurrentBand = [NaN NaN];
                app.CurrentBandIndices = [];
                app.CurrentMap = [];
                app.RawCurrentMap = [];
                app.FilteredCurrentMap = [];
                
                app.FilterWasApplied = false;
                app.FilterSettings = struct();
                
                app.BandFilters = table( ...
                    'Size', [0 6], ...
                    'VariableTypes', ...
                    {'double', 'string', 'double', 'double', ...
                    'string', 'double'}, ...
                    'VariableNames', ...
                    {'ID', 'Name', 'LowWN', 'HighWN', ...
                    'Method', 'PointCount'});
                
                app.CurrentFilterIndex = NaN;
                
                % =====================================================
                % 4. AUTOMATICALLY MATCH CUBE ORIENTATION TO TRACE
                % =====================================================
                
                loadingDialog.Message = ...
                    'Automatically matching Raman orientation to trace...';
                
                autoOrientCubeToTrace(app);
                
                % Trace displayed on the Raman pixel grid.
                synchronizeTraceToCubeGrid(app);
                
                % Recalculate after orientation.
                updateAcquiredMask(app);
                
                loadingDialog.Message = 'Calculating mean spectrum...';
                
                app.MeanSpectrum = calculateMeanSpectrum(app);
                
                % Ensure vector is consistent with oriented cube.
                synchronizeIntensityVectorFromCube(app);
                
                % =====================================================
                % 5. ENABLE APP
                % =====================================================
                
                % app.TextArea.Value = { ...
                %     'SCAN LOADED'; ...
                %     '================================'; ...
                %     ''; ...
                %     ['RAW folder: ' char(app.SourceFolder)]; ...
                %     ['Trace: ' char(app.TraceFile)]; ...
                %     ''; ...
                %     sprintf('Cube size: %d x %d pixels', ...
                %     size(app.Cube, 1), size(app.Cube, 2)); ...
                %     sprintf('Original trace size: %d x %d pixels', ...
                %     size(app.TraceImageOriginal, 1), ...
                %     size(app.TraceImageOriginal, 2)); ...
                %     sprintf('Registration score: %.4f', ...
                %     app.AutoOrientationScore); ...
                %     ['Registration: ' app.AutoOrientationMethod]};
                
                app.IsLoaded = true;
                
                app.UIFigure.WindowButtonMotionFcn = ...
                    @(src,event) figureMouseMoved(app);
                
                app.BandplotButton.Enable = 'on';
                app.GeneratescandataButton.Enable = 'on';
                app.ApplyfilterButton.Enable = 'on';
                
                app.CurrentMode = app.WorkingmodesDropDown.Value;
                
                refreshDisplay(app);
                
                if isvalid(loadingDialog)
                    close(loadingDialog);
                end
                
                nRows = size(app.Cube, 1);
                nColumns = size(app.Cube, 2);
                nWavenumbers = size(app.Cube, 3);
                
                message = sprintf([ ...
                    'Scan loaded successfully.\n\n' ...
                    'Raman grid: %d x %d\n' ...
                    'Trace original: %d x %d\n' ...
                    'Wavenumber points: %d\n' ...
                    'Number of spectra: %d\n\n' ...
                    'Automatic registration score: %.4f\n' ...
                    '%s'], ...
                    nRows, ...
                    nColumns, ...
                    size(app.TraceImageOriginal, 1), ...
                    size(app.TraceImageOriginal, 2), ...
                    nWavenumbers, ...
                    nRows * nColumns, ...
                    app.AutoOrientationScore, ...
                    app.AutoOrientationMethod);
                
                uialert( ...
                    app.UIFigure, ...
                    message, ...
                    'Raman scan loaded', ...
                    'Icon', 'success');
                
            catch ME
                
                if ~isempty(loadingDialog) && isvalid(loadingDialog)
                    close(loadingDialog);
                end
                
                app.IsLoaded = false;
                
                uialert( ...
                    app.UIFigure, ...
                    sprintf('Could not load scan:\n\n%s', ME.message), ...
                    'Load error', ...
                    'Icon', 'error');
            end
        end

        % Value changed function: MapcolorsDropDown
        function MapcolorsDropDownValueChanged(app, event)
            value = app.MapcolorsDropDown.Value;
            selectedMap = lower(app.MapcolorsDropDown.Value);
            
            switch selectedMap
                case 'turbo'
                    app.CurrentColormap = 'turbo';
                    
                case 'jet'
                    app.CurrentColormap = 'jet';
                    
                case 'hot'
                    app.CurrentColormap = 'hot';
                    
                case 'gray'
                    app.CurrentColormap = 'gray';
                    
                case 'parula'
                    app.CurrentColormap = 'parula';
                    
                case 'bone'
                    app.CurrentColormap = 'bone';
                    
                otherwise
                    app.CurrentColormap = 'turbo';
            end
            
            refreshDisplay(app);
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            
        end

        % Value changed function: WorkingmodesDropDown
        function WorkingmodesDropDownValueChanged(app, event)
            app.CurrentMode = char(app.WorkingmodesDropDown.Value);
            
            app.LastHoverRow = NaN;
            app.LastHoverColumn = NaN;
            
            app.HoverMarker = [];
            app.HoverSpectrumLine = [];
            
            % Ensure mouse callback remains active
            app.UIFigure.WindowButtonMotionFcn = ...
                @(src,event) figureMouseMoved(app);
            
            refreshDisplay(app);
        end

        % Button pushed function: GeneratescandataButton
        function GeneratescandataButtonPushed(app, event)
            if ~app.IsLoaded || isempty(app.Cube)
                
                uialert( ...
                    app.UIFigure, ...
                    'Load and reconstruct a Raman scan first.', ...
                    'No scan data', ...
                    'Icon', 'warning');
                return;
            end
            
            if isempty(app.TraceImageOriginal) || ...
                    strlength(app.TraceFile) == 0
                
                uialert( ...
                    app.UIFigure, ...
                    'A valid trace image is required before generation.', ...
                    'Missing trace', ...
                    'Icon', 'error');
                return;
            end
            
            % =========================================================
            % 1. DEFAULT NAME = RAW SOURCE FOLDER NAME
            % =========================================================
            
            [~, rawFolderName] = ...
                fileparts(char(app.SourceFolder));
            
            if isempty(rawFolderName)
                rawFolderName = 'Prepared_scan';
            end
            
            answer = inputdlg( ...
                {'Output folder name:'}, ...
                'Generate readable scan', ...
                [1 60], ...
                {rawFolderName});
            
            if isempty(answer)
                return;
            end
            
            folderName = char(strtrim(string(answer{1})));
            
            if isempty(folderName)
                
                uialert( ...
                    app.UIFigure, ...
                    'Folder name cannot be empty.', ...
                    'Invalid folder name', ...
                    'Icon', 'error');
                return;
            end
            
            folderName = regexprep(folderName, '[<>:"/\\|?*]', '_');
            folderName = regexprep(folderName, '\s+', '_');
            folderName = regexprep(folderName, '[\. ]+$', '');
            
            if isempty(folderName)
                folderName = rawFolderName;
            end
            
            % =========================================================
            % 2. FIXED READABLE OUTPUT ROOT
            % =========================================================
            
            outputRoot = char(app.ReadableScanRoot);
            
            try
                if ~isfolder(outputRoot)
                    mkdir(outputRoot);
                end
            catch ME
                
                uialert( ...
                    app.UIFigure, ...
                    sprintf([ ...
                    'Could not create READABLE folder:\n\n%s\n\n%s'], ...
                    outputRoot, ...
                    ME.message), ...
                    'Output folder error', ...
                    'Icon', 'error');
                return;
            end
            
            outputFolder = fullfile(outputRoot, folderName);
            
            if isfolder(outputFolder)
                
                choice = uiconfirm( ...
                    app.UIFigure, ...
                    sprintf([ ...
                    'The readable scan folder already exists:\n\n%s\n\n' ...
                    'Overwrite its generated MAT and trace files?'], ...
                    outputFolder), ...
                    'Folder already exists', ...
                    'Options', {'Overwrite', 'Cancel'}, ...
                    'DefaultOption', 'Cancel', ...
                    'CancelOption', 'Cancel');
                
                if strcmp(choice, 'Cancel')
                    return;
                end
            else
                mkdir(outputFolder);
            end
            
            progressDialog = uiprogressdlg( ...
                app.UIFigure, ...
                'Title', 'Generating readable scan', ...
                'Message', 'Synchronizing Raman data...', ...
                'Indeterminate', 'on');
            
            try
                % Ensure all saved representations are consistent.
                synchronizeIntensityVectorFromCube(app);
                updateAcquiredMask(app);
                
                app.MeanSpectrum = calculateMeanSpectrum(app);
                
                % Do not recalculate CurrentMap here because doing so would discard
                % an accepted filtered map.
                
                if isempty(app.CurrentMap) && ...
                        ~isempty(app.BandFilters) && ...
                        ~isnan(app.CurrentFilterIndex)
                    
                    calculateCurrentFilterMap(app);
                end
                
                progressDialog.Message = ...
                    'Building RamanData structure...';
                
                RamanData = buildRamanDataStructure( ...
                    app, ...
                    outputFolder);
                
                % Additional registration/filter provenance.
                RamanData.TraceRegistration = struct();
                
                RamanData.TraceRegistration.Automatic = ...
                    app.AutoOrientationWasApplied;
                
                RamanData.TraceRegistration.Score = ...
                    app.AutoOrientationScore;
                
                RamanData.TraceRegistration.Method = ...
                    app.AutoOrientationMethod;
                
                RamanData.TraceRegistration.OriginalTraceSize = ...
                    size(app.TraceImageOriginal);
                
                RamanData.TraceRegistration.RamanGridSize = ...
                    [size(app.Cube, 1), size(app.Cube, 2)];
                
                RamanData.FilterWasApplied = ...
                    app.FilterWasApplied;
                
                RamanData.FilterSettings = ...
                    app.FilterSettings;
                
                RamanData.RawCurrentMap = ...
                    app.RawCurrentMap;
                
                RamanData.FilteredCurrentMap = ...
                    app.FilteredCurrentMap;
                
                outputMAT = fullfile( ...
                    outputFolder, ...
                    [folderName '_RamanData.mat']);
                
                progressDialog.Message = ...
                    'Saving MAT file...';
                
                save(outputMAT, 'RamanData', '-v7.3');
                
                % =====================================================
                % 3. SAVE TRACE WITH A FIXED, PREDICTABLE NAME
                % =====================================================
                
                progressDialog.Message = ...
                    'Saving trace images...';
                
                [~, ~, traceExtension] = ...
                    fileparts(char(app.TraceFile));
                
                if isempty(traceExtension)
                    traceExtension = '.tif';
                end
                
                originalTraceOutput = fullfile( ...
                    outputFolder, ...
                    ['Trace_original' traceExtension]);
                
                alignedTraceOutput = fullfile( ...
                    outputFolder, ...
                    ['Trace_RamanGrid' traceExtension]);
                
                imwrite(app.TraceImageOriginal, originalTraceOutput);
                imwrite(app.TraceImage, alignedTraceOutput);
                
                app.GeneratedMatPath = outputMAT;
                app.GeneratedScanName = folderName;
                
                % =====================================================
                % 4. RETURN TO PARENT APP
                % =====================================================
                
                preparedPayload = struct();
                
                preparedPayload.ScanName = string(RamanData.ScanName);
                preparedPayload.ScanID = string(RamanData.ScanID);
                preparedPayload.Stage = "Prepared raw scan";
                
                preparedPayload.Cube = double(RamanData.Cube);
                preparedPayload.Wavenumber = ...
                    double(RamanData.Wavenumber(:));
                
                preparedPayload.ValidMask = ...
                    logical(RamanData.ValidMask);
                
                preparedPayload.SourceMatPath = string(outputMAT);
                preparedPayload.SourceFolder = string(outputFolder);
                
                preparedPayload.TraceImage = ...
                    RamanData.TraceImage;
                
                preparedPayload.TraceImageOriginal = ...
                    RamanData.TraceImageOriginal;
                
                preparedPayload.Orientation = ...
                    RamanData.Orientation;
                
                preparedPayload.TraceRegistration = ...
                    RamanData.TraceRegistration;
                
                try
                    if ~isempty(app.mainApp) && ...
                            isvalid(app.mainApp) && ...
                            ismethod(app.mainApp, 'receivePreparedScan')
                        
                        app.mainApp.receivePreparedScan(preparedPayload);
                    end
                    
                catch MEparent
                    warning( ...
                        'RamanCube:PreparedScanReturnFailed', ...
                        '%s', ...
                        MEparent.message);
                end
                
                if isvalid(progressDialog)
                    close(progressDialog);
                end
                
                uialert( ...
                    app.UIFigure, ...
                    sprintf([ ...
                    'Readable scan generated successfully.\n\n' ...
                    'Folder:\n%s\n\n' ...
                    'MAT:\n%s\n\n' ...
                    'Trace original:\n%s\n\n' ...
                    'Trace on Raman grid:\n%s'], ...
                    outputFolder, ...
                    outputMAT, ...
                    originalTraceOutput, ...
                    alignedTraceOutput), ...
                    'Generation complete', ...
                    'Icon', 'success');
                
            catch ME
                
                if ~isempty(progressDialog) && ...
                        isvalid(progressDialog)
                    
                    close(progressDialog);
                end
                
                uialert( ...
                    app.UIFigure, ...
                    sprintf([ ...
                    'Could not generate scan data.\n\n' ...
                    'Identifier:\n%s\n\n' ...
                    'Message:\n%s'], ...
                    ME.identifier, ...
                    ME.message), ...
                    'Generation error', ...
                    'Icon', 'error');
            end
        end

        % Button pushed function: BandplotButton
        function BandplotButtonPushed(app, event)
            if ~app.IsLoaded || isempty(app.Cube)
                uialert(app.UIFigure, ...
                    'Load a Raman scan first.', ...
                    'No scan loaded', ...
                    'Icon', 'warning');
                return;
            end
            
            openBandFilterManager(app);
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if ~app.IsLoaded
                return;
            end
            
            % Rotate Raman cube only.
            app.Cube = ...
                rot90(app.Cube, 1);
            
            % Keep acquired-region mask aligned with the cube.
            if ~isempty(app.AcquiredMask2D)
                
                app.AcquiredMask2D = ...
                    rot90(app.AcquiredMask2D, 1);
            end
            
            % Trace image intentionally remains unchanged.
            
            app.RotationQuarterTurns = ...
                mod(app.RotationQuarterTurns + 1, 4);
            
            app.OrientationHistory{end + 1} = ...
                'Rotate Raman cube counterclockwise 90 degrees; trace unchanged';
            
            recalculateCurrentMapAfterOrientation(app);
            synchronizeTraceToCubeGrid(app);
            synchronizeIntensityVectorFromCube(app);
            
            app.LastHoverRow = NaN;
            app.LastHoverColumn = NaN;
            app.HoverMarker = [];
            app.HoverSpectrumLine = [];
            
            refreshDisplay(app);
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if ~app.IsLoaded
                return;
            end
            
            % Rotate Raman cube only.
            app.Cube = ...
                rot90(app.Cube, -1);
            
            if ~isempty(app.AcquiredMask2D)
                
                app.AcquiredMask2D = ...
                    rot90(app.AcquiredMask2D, -1);
            end
            
            % Trace image intentionally remains unchanged.
            
            app.RotationQuarterTurns = ...
                mod(app.RotationQuarterTurns - 1, 4);
            
            app.OrientationHistory{end + 1} = ...
                'Rotate Raman cube clockwise 90 degrees; trace unchanged';
            
            recalculateCurrentMapAfterOrientation(app);
            synchronizeTraceToCubeGrid(app);
            synchronizeIntensityVectorFromCube(app);
            
            app.LastHoverRow = NaN;
            app.LastHoverColumn = NaN;
            app.HoverMarker = [];
            app.HoverSpectrumLine = [];
            
            refreshDisplay(app);
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            if ~app.IsLoaded
                return;
            end
            
            % Flip Raman cube only.
            app.Cube = ...
                flip(app.Cube, 1);
            
            if ~isempty(app.AcquiredMask2D)
                
                app.AcquiredMask2D = ...
                    flip(app.AcquiredMask2D, 1);
            end
            
            % Trace image intentionally remains unchanged.
            
            app.FlipVertical = ...
                ~app.FlipVertical;
            
            app.OrientationHistory{end + 1} = ...
                'Flip Raman cube vertically; trace unchanged';
            
            recalculateCurrentMapAfterOrientation(app);
            synchronizeTraceToCubeGrid(app);
            synchronizeIntensityVectorFromCube(app);
            
            app.LastHoverRow = NaN;
            app.LastHoverColumn = NaN;
            app.HoverMarker = [];
            app.HoverSpectrumLine = [];
            
            refreshDisplay(app);
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            if ~app.IsLoaded
                return;
            end
            
            % Flip Raman cube only.
            app.Cube = ...
                flip(app.Cube, 2);
            
            if ~isempty(app.AcquiredMask2D)
                
                app.AcquiredMask2D = ...
                    flip(app.AcquiredMask2D, 2);
            end
            
            % Trace image intentionally remains unchanged.
            
            app.FlipHorizontal = ...
                ~app.FlipHorizontal;
            
            app.OrientationHistory{end + 1} = ...
                'Flip Raman cube horizontally; trace unchanged';
            
            recalculateCurrentMapAfterOrientation(app);
            synchronizeTraceToCubeGrid(app);
            synchronizeIntensityVectorFromCube(app);
            
            app.LastHoverRow = NaN;
            app.LastHoverColumn = NaN;
            app.HoverMarker = [];
            app.HoverSpectrumLine = [];
            
            refreshDisplay(app);
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            try
                % =============================================================
                % 1. REQUIRE A GENERATED / PREPARED SCAN
                % =============================================================
                
                if isempty(app.LastPreparedPayload) || ...
                        ~isstruct(app.LastPreparedPayload) || ...
                        ~isfield(app.LastPreparedPayload, 'Cube') || ...
                        isempty(app.LastPreparedPayload.Cube)
                    
                    choice = uiconfirm( ...
                        app.UIFigure, ...
                        { ...
                        'No prepared Raman scan is available yet.'; ...
                        ''; ...
                        'Generate the Raman scan before finishing.'; ...
                        ''; ...
                        'Return to scanspectra without a scan?'}, ...
                        'No generated scan', ...
                        'Options', { ...
                        'Return without scan', ...
                        'Stay here'}, ...
                        'DefaultOption', ...
                        'Stay here', ...
                        'CancelOption', ...
                        'Stay here');
                    
                    if ~strcmp(choice, 'Return without scan')
                        return
                    end
                    
                    % Return to scanspectra without sending data.
                    if ~isempty(app.mainApp) && ...
                            isvalid(app.mainApp)
                        
                        app.mainApp.UIFigure.Visible = 'on';
                        
                        try
                            figure(app.mainApp.UIFigure);
                        catch
                        end
                        
                        if ismethod(app.mainApp, 'addHistory')
                            app.mainApp.addHistory( ...
                                'Returned from Loadscan without generating a scan.');
                        end
                    end
                    
                    app.UIFigure.Visible = 'off';
                    return
                end
                
                % =============================================================
                % 2. VALIDATE PARENT SCANSPECTRA APP
                % =============================================================
                
                if isempty(app.mainApp) || ...
                        ~isvalid(app.mainApp)
                    
                    uialert( ...
                        app.UIFigure, ...
                        ['The scanspectra parent app is no longer available.' ...
                        newline newline ...
                        'The generated MAT file remains saved in READABLE, ' ...
                        'but it cannot be returned automatically.'], ...
                        'Parent app unavailable', ...
                        'Icon', 'warning');
                    
                    return
                end
                
                if ~ismethod(app.mainApp, 'receivePreparedScan')
                    
                    error([ ...
                        'scanspectra does not contain receivePreparedScan().' ...
                        newline ...
                        'The prepared scan cannot be transferred automatically.']);
                end
                
                % =============================================================
                % 3. BUILD FINAL PAYLOAD
                % =============================================================
                
                payload = ...
                    app.LastPreparedPayload;
                
                requiredFields = { ...
                    'ScanName', ...
                    'ScanID', ...
                    'Cube', ...
                    'Wavenumber', ...
                    'ValidMask', ...
                    'SourceMatPath'};
                
                for k = 1:numel(requiredFields)
                    
                    fieldName = ...
                        requiredFields{k};
                    
                    if ~isfield(payload, fieldName) || ...
                            isempty(payload.(fieldName))
                        
                        error( ...
                            'Prepared payload is missing field: %s', ...
                            fieldName);
                    end
                end
                
                if ndims(payload.Cube) ~= 3
                    error('Prepared payload Cube must be a 3-D Raman cube.');
                end
                
                [ny, nx, nWn] = ...
                    size(payload.Cube);
                
                if numel(payload.Wavenumber) ~= nWn
                    
                    error([ ...
                        'Prepared payload Wavenumber does not match ' ...
                        'the cube spectral dimension.']);
                end
                
                if ~isequal(size(payload.ValidMask), [ny nx])
                    
                    payload.ValidMask = ...
                        all(isfinite(payload.Cube), 3);
                else
                    
                    payload.ValidMask = ...
                        logical(payload.ValidMask) & ...
                        all(isfinite(payload.Cube), 3);
                end
                
                if ~any(payload.ValidMask(:))
                    error('Prepared Raman scan contains no valid pixels.');
                end
                
                % Normalize fields before transfer.
                payload.Cube = ...
                    double(payload.Cube);
                
                payload.Wavenumber = ...
                    double(payload.Wavenumber(:));
                
                payload.ValidMask = ...
                    logical(payload.ValidMask);
                
                payload.ScanName = ...
                    string(payload.ScanName);
                
                payload.ScanID = ...
                    string(payload.ScanID);
                
                payload.SourceMatPath = ...
                    string(payload.SourceMatPath);
                
                payload.SourceApp = ...
                    "Loadscan";
                
                payload.SourceMode = ...
                    "New scan generated by Loadscan";
                
                payload.SourceStage = ...
                    "Original";
                
                payload.Stage = ...
                    "Original prepared Raman scan";
                
                payload.ReturnedToScanSpectraAt = ...
                    string(datestr(now, 'yyyy-mm-dd HH:MM:SS'));
                
                % =============================================================
                % 4. SEND THE PREPARED SCAN TO SCANSPECTRA
                %
                % receivePreparedScan stores it as:
                %   OriginalLoadedScan
                %   ActiveLoadedScan
                % =============================================================
                
                app.mainApp.receivePreparedScan(payload);
                
                % =============================================================
                % 5. WRITE HISTORY
                % =============================================================
                
                if ismethod(app.mainApp, 'addHistory')
                    
                    app.mainApp.addHistory(sprintf( ...
                        'Prepared Raman scan returned from Loadscan: %s', ...
                        char(payload.ScanName)));
                    
                    app.mainApp.addHistory([ ...
                        'Prepared MAT: ' ...
                        char(payload.SourceMatPath)]);
                    
                    app.mainApp.addHistory(sprintf( ...
                        'Prepared cube size: %d x %d x %d', ...
                        ny, nx, nWn));
                    
                    app.mainApp.addHistory(sprintf( ...
                        'Valid pixels: %d / %d', ...
                        nnz(payload.ValidMask), ...
                        numel(payload.ValidMask)));
                end
                
                % =============================================================
                % 6. SHOW SUCCESS MESSAGE
                % =============================================================
                
                msgbox( ...
                    sprintf([ ...
                    'Raman scan preparation completed successfully.\n\n' ...
                    'Scan: %s\n' ...
                    'Cube size: %d x %d x %d\n' ...
                    'Valid pixels: %d / %d\n\n' ...
                    'Saved MAT:\n%s\n\n' ...
                    'The scan has been loaded into scanspectra and is ready ' ...
                    'for Scan pre-treat.'], ...
                    char(payload.ScanName), ...
                    ny, nx, nWn, ...
                    nnz(payload.ValidMask), ...
                    numel(payload.ValidMask), ...
                    char(payload.SourceMatPath)), ...
                    'Raman scan ready', ...
                    'modal');
                
                % =============================================================
                % 7. RETURN TO SCANSPECTRA
                % =============================================================
                
                app.mainApp.UIFigure.Visible = ...
                    'on';
                
                try
                    figure(app.mainApp.UIFigure);
                catch
                end
                
                app.UIFigure.Visible = ...
                    'off';
                
            catch ME
                
                uialert( ...
                    app.UIFigure, ...
                    getReport( ...
                    ME, ...
                    'extended', ...
                    'hyperlinks', ...
                    'off'), ...
                    'Finish Loadscan error', ...
                    'Icon', ...
                    'error');
            end


        end

        % Button pushed function: ResizescanButton
        function ResizescanButtonPushed(app, event)
            % =========================================================
            % 1. VALIDATE
            % =========================================================
            
            if ~app.IsLoaded || ...
                    isempty(app.Cube)
                
                uialert( ...
                    app.UIFigure, ...
                    'Load a Raman scan first.', ...
                    'No Raman scan', ...
                    'Icon', ...
                    'warning');
                
                return;
            end
            
            if isempty(app.CurrentMap)
                
                uialert( ...
                    app.UIFigure, ...
                    { ...
                    'Select a Raman band first.'; ...
                    ''; ...
                    'The band map is used to draw the crop rectangle.'}, ...
                    'No band map', ...
                    'Icon', ...
                    'warning');
                
                return;
            end
            
            bandAxes = ...
                app.getCurrentBandAxes();
            
            if isempty(bandAxes) || ...
                    ~isvalid(bandAxes)
                
                uialert( ...
                    app.UIFigure, ...
                    { ...
                    'Resize requires a visible Raman band map.'; ...
                    ''; ...
                    'Choose Trace - Band or Band - Spectra mode.'}, ...
                    'Band map not visible', ...
                    'Icon', ...
                    'warning');
                
                return;
            end
            
            % =========================================================
            % 2. USER DRAWS CROP RECTANGLE
            % =========================================================
            
            % app.TextArea.Value = { ...
            %     'RAMAN CUBE RESIZE'; ...
            %     '================================'; ...
            %     ''; ...
            %     'Draw a rectangle around the acquired Raman region.'; ...
            %     'Double-click the rectangle to confirm.'; ...
            %     ''; ...
            %     'Only the Raman cube will be cropped.'; ...
            %     'The trace image on Axis 1 will remain unchanged.'};
            
            drawnow;
            
            roi = [];
            
            try
                
                roi = ...
                    drawrectangle( ...
                    bandAxes, ...
                    'Color', ...
                    [0.10 0.90 0.25], ...
                    'LineWidth', ...
                    2, ...
                    'FaceAlpha', ...
                    0.08, ...
                    'InteractionsAllowed', ...
                    'all');
                
                wait(roi);
                
                if isempty(roi) || ...
                        ~isvalid(roi)
                    
                    return;
                end
                
                position = ...
                    double(roi.Position);
                
                delete(roi);
                roi = [];
                
                % drawrectangle position:
                % [x, y, width, height]
                x1 = ...
                    floor(position(1));
                
                y1 = ...
                    floor(position(2));
                
                x2 = ...
                    ceil(position(1) + position(3));
                
                y2 = ...
                    ceil(position(2) + position(4));
                
                numberRows = ...
                    size(app.Cube, 1);
                
                numberColumns = ...
                    size(app.Cube, 2);
                
                columnStart = ...
                    max(1, min(numberColumns, x1));
                
                columnEnd = ...
                    max(1, min(numberColumns, x2));
                
                rowStart = ...
                    max(1, min(numberRows, y1));
                
                rowEnd = ...
                    max(1, min(numberRows, y2));
                
                if columnStart > columnEnd
                    
                    temporaryValue = columnStart;
                    columnStart = columnEnd;
                    columnEnd = temporaryValue;
                end
                
                if rowStart > rowEnd
                    
                    temporaryValue = rowStart;
                    rowStart = rowEnd;
                    rowEnd = temporaryValue;
                end
                
                newRows = ...
                    rowEnd - rowStart + 1;
                
                newColumns = ...
                    columnEnd - columnStart + 1;
                
                if newRows < 2 || ...
                        newColumns < 2
                    
                    uialert( ...
                        app.UIFigure, ...
                        'The selected crop is too small.', ...
                        'Invalid crop', ...
                        'Icon', ...
                        'warning');
                    
                    return;
                end
                
                % =========================================================
                % 3. CONFIRM DESTRUCTIVE CROP OF WORKING CUBE
                % =========================================================
                
                choice = ...
                    uiconfirm( ...
                    app.UIFigure, ...
                    { ...
                    sprintf( ...
                    'Crop Raman cube from %d x %d to %d x %d pixels?', ...
                    numberRows, ...
                    numberColumns, ...
                    newRows, ...
                    newColumns); ...
                    ''; ...
                    sprintf( ...
                    'Rows retained: %d-%d', ...
                    rowStart, ...
                    rowEnd); ...
                    sprintf( ...
                    'Columns retained: %d-%d', ...
                    columnStart, ...
                    columnEnd); ...
                    ''; ...
                    'The trace image will not be cropped or rotated.'; ...
                    'CubeOriginal remains available as the original import.'}, ...
                    'Confirm Raman cube crop', ...
                    'Options', ...
                    {'Crop Raman cube', 'Cancel'}, ...
                    'DefaultOption', ...
                    'Crop Raman cube', ...
                    'CancelOption', ...
                    'Cancel');
                
                if ~strcmp(choice, 'Crop Raman cube')
                    
                    return;
                end
                
                % =========================================================
                % 4. RECORD CROP
                % =========================================================
                
                cropRecord = struct();
                
                cropRecord.Time = ...
                    datetime('now');
                
                cropRecord.OldSize = ...
                    [numberRows numberColumns];
                
                cropRecord.NewSize = ...
                    [newRows newColumns];
                
                cropRecord.RowRange = ...
                    [rowStart rowEnd];
                
                cropRecord.ColumnRange = ...
                    [columnStart columnEnd];
                
                app.CropHistory{end + 1} = ...
                    cropRecord;
                
                % =========================================================
                % 5. CROP RAMAN DATA ONLY
                % =========================================================
                
                app.Cube = ...
                    app.Cube( ...
                    rowStart:rowEnd, ...
                    columnStart:columnEnd, ...
                    :);
                
                % Do not modify:
                %   app.TraceImage
                %   app.TraceImageOriginal
                
                app.IsSpatiallyCropped = ...
                    true;
                
                % Update current working dimensions.
                app.HeaderInfo.SizeX = ...
                    size(app.Cube, 1);
                
                app.HeaderInfo.SizeY = ...
                    size(app.Cube, 2);
                
                % Keep vector and cube mutually consistent.
                app.synchronizeIntensityVectorFromCube();
                
                % Recalculate acquired mask and mean spectrum.
                app.updateAcquiredMask();
                
                app.MeanSpectrum = ...
                    app.calculateMeanSpectrum();
                
                % Recalculate the selected Raman band after cropping.
                app.recalculateCurrentMapAfterOrientation();
                
                % =========================================================
                % 6. RESET HOVER STATE
                % =========================================================
                
                app.LastHoverRow = NaN;
                app.LastHoverColumn = NaN;
                
                app.HoverMarker = [];
                app.HoverSpectrumLine = [];
                
                % =========================================================
                % 7. REFRESH
                % =========================================================
                
                app.refreshDisplay();
                
                if ~isempty(app.FilterFigure) && ...
                        isvalid(app.FilterFigure)
                    
                    app.refreshFilterManager();
                end
                
                % app.TextArea.Value = { ...
                %     'RAMAN CUBE RESIZED'; ...
                %     '================================'; ...
                %     ''; ...
                %     sprintf( ...
                %     'Previous size: %d x %d pixels', ...
                %     numberRows, ...
                %     numberColumns); ...
                %     sprintf( ...
                %     'Current size: %d x %d pixels', ...
                %     size(app.Cube, 1), ...
                %     size(app.Cube, 2)); ...
                %     sprintf( ...
                %     'Rows retained: %d-%d', ...
                %     rowStart, ...
                %     rowEnd); ...
                %     sprintf( ...
                %     'Columns retained: %d-%d', ...
                %     columnStart, ...
                %     columnEnd); ...
                %     ''; ...
                %     sprintf( ...
                %     'Acquired pixels retained: %d / %d', ...
                %     nnz(app.AcquiredMask2D), ...
                %     numel(app.AcquiredMask2D)); ...
                %     ''; ...
                %     'Trace image: unchanged'; ...
                %     'Raman cube: cropped'; ...
                %     'Display scaling: acquired Raman pixels only'};
                
            catch ME
                
                if ~isempty(roi) && ...
                        isvalid(roi)
                    
                    delete(roi);
                end
                
                uialert( ...
                    app.UIFigure, ...
                    ME.message, ...
                    'Resize error', ...
                    'Icon', ...
                    'error');
            end
            
        end

        % Button pushed function: ApplyfilterButton
        function ApplyfilterButtonPushed(app, event)
            if ~app.IsLoaded || isempty(app.Cube)
                
                uialert( ...
                    app.UIFigure, ...
                    'Load a Raman scan first.', ...
                    'No scan loaded', ...
                    'Icon', 'warning');
                return;
            end
            
            if isempty(app.CurrentMap)
                
                uialert( ...
                    app.UIFigure, ...
                    ['Select a Raman band before opening the ' ...
                    'image-filter preview.'], ...
                    'No Raman band', ...
                    'Icon', 'warning');
                return;
            end
            
            openImageFilterPreview(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Trace')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Raman band')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.LoadscanButton = uibutton(app.GridLayout2, 'push');
            app.LoadscanButton.ButtonPushedFcn = createCallbackFcn(app, @LoadscanButtonPushed, true);
            app.LoadscanButton.Text = 'Load scan';
            app.LoadRamanscanLabel = uilabel(app.GridLayout2);
            app.LoadRamanscanLabel.Text = 'Load Raman scan';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.BandplotButton = uibutton(app.GridLayout3, 'push');
            app.BandplotButton.ButtonPushedFcn = createCallbackFcn(app, @BandplotButtonPushed, true);
            app.BandplotButton.Text = 'Band plot';
            app.ResizescanButton = uibutton(app.GridLayout3, 'push');
            app.ResizescanButton.ButtonPushedFcn = createCallbackFcn(app, @ResizescanButtonPushed, true);
            app.ResizescanButton.Text = 'Resize scan';
            app.ApplyfilterButton = uibutton(app.GridLayout3, 'push');
            app.ApplyfilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyfilterButtonPushed, true);
            app.ApplyfilterButton.Text = 'Apply filter';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.WorkingmodesDropDownLabel = uilabel(app.GridLayout4);
            app.WorkingmodesDropDownLabel.Text = 'Working modes';
            app.WorkingmodesDropDown = uidropdown(app.GridLayout4);
            app.WorkingmodesDropDown.Items = {'', 'Trace - Band', 'Band - Spectra', 'Trace - Spectra'};
            app.WorkingmodesDropDown.ValueChangedFcn = createCallbackFcn(app, @WorkingmodesDropDownValueChanged, true);
            app.WorkingmodesDropDown.Value = '';
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.MapcolorsDropDownLabel = uilabel(app.GridLayout5);
            app.MapcolorsDropDownLabel.Text = 'Map colors';
            app.MapcolorsDropDown = uidropdown(app.GridLayout5);
            app.MapcolorsDropDown.Items = {'', 'Jet', 'Hot', 'Gray', 'Turbo'};
            app.MapcolorsDropDown.ValueChangedFcn = createCallbackFcn(app, @MapcolorsDropDownValueChanged, true);
            app.MapcolorsDropDown.Value = '';
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.GeneratescandataButton = uibutton(app.GridLayout6, 'push');
            app.GeneratescandataButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratescandataButtonPushed, true);
            app.GeneratescandataButton.Text = 'Generate scan data';
            app.FinishButton = uibutton(app.GridLayout6, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.GridLayout7 = uigridlayout(app.GridLayout);
            app.Button = uibutton(app.GridLayout7, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, '1.png');
            app.Button.Text = '';
            app.Button_2 = uibutton(app.GridLayout7, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = fullfile(pathToMLAPP, '2.png');
            app.Button_2.Text = '';
            app.Button_5 = uibutton(app.GridLayout7, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Icon = fullfile(pathToMLAPP, '3.png');
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.GridLayout7, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Icon = fullfile(pathToMLAPP, '4.png');
            app.Button_6.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Loadscan

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end