classdef candidate_confirm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        SettingsPanel             matlab.ui.container.Panel
        GridLayout2               matlab.ui.container.GridLayout
        AddcandidatesButton       matlab.ui.control.Button
        FinishButton              matlab.ui.control.Button
        ExcelexportButton         matlab.ui.control.Button
        ConfirmcandidatesButton   matlab.ui.control.Button
        ValidatecandidateButton   matlab.ui.control.Button
        DiscovercandidatesButton  matlab.ui.control.Button
        LoaddataButton            matlab.ui.control.Button
        UITable                   matlab.ui.control.Table
        TextArea                  matlab.ui.control.TextArea
        UIAxes_4                  matlab.ui.control.UIAxes
        UIAxes_3                  matlab.ui.control.UIAxes
        UIAxes_2                  matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % ================================================================
        % WORKFLOW STATE
        % ================================================================
        
        WorkflowStage string = "EMPTY"
        
        % Possible values:
        %
        % EMPTY
        % DATA_READY
        % DISCOVERY_READY
        % CANDIDATE_SELECTED
        % VALIDATED
        % REVIEWED
        
        
        % ================================================================
        % SOURCE SESSION / PREPARATION
        % ================================================================
        
        SessionFile string = ""
        
        PreparedData struct = struct()
        
        AnalysisData struct = struct()
        
        
        % ================================================================
        % ANALYSIS DATA
        % ================================================================
        
        wn double = []
        
        X_unorm double = []
        
        X_norm double = []
        
        VectorNormFactors double = []
        
        analysisGroupID string = strings(0,1)
        
        analysisGroupNames string = strings(0,1)
        
        groupA string = ""
        
        groupB string = ""
        
        
        % ================================================================
        % DISCOVERY
        % ================================================================
        
        ROITable table = table()
        
        FeatureValues table = table()
        
        PrePCA struct = struct()
        
        
        % ================================================================
        % PCA
        % ================================================================
        
        PCA_Full struct = struct()
        
        PCA_Fingerprint struct = struct()
        
        PCA_HighWN struct = struct()
        
        
        % ================================================================
        % CURRENT CANDIDATE
        % ================================================================
        
        SelectedCandidateRow double = NaN
        
        SelectedCandidateID string = ""
        
        SelectedCandidateType string = ""
        
        SelectedCandidate struct = struct()
        
        
        % ================================================================
        % CURRENT VALIDATION
        % ================================================================
        
        CurrentValidation struct = struct()
        
        HasCurrentValidation logical = false
        
        
        % ================================================================
        % ALL REVIEW RESULTS
        %
        % One element per candidate after validation/review.
        % ================================================================
        
        CandidateResults struct = struct( ...
            'CandidateID',{}, ...
            'Mode',{}, ...
            'Decision',{}, ...
            'ValidatedAt',{}, ...
            'Result',{})
        
        
        % ================================================================
        % OUTPUT
        % ================================================================
        
        OutputRoot string = ""
        
        EvidenceRoot string = ""
        
        ExcelPath string = ""
        
        AnalysisCheckpointPath string = ""
        
        
        % ================================================================
        % SETTINGS
        %
        % All thresholds live in ONE structure.
        % ================================================================
        
        Settings struct = struct()
        MorphologyCandidates table = table()
        MorphologyApp = []
        ValidationCache struct = struct( ...
            'CandidateID',{}, ...
            'Mode',{}, ...
            'Result',{})
    end
    
    
    methods (Access = public)
        
        function receiveMorphologyCandidates(app,Results)
            
            app.MorphologyApp = [];
            
            if isempty(Results)
                app.MorphologyCandidates = table();
            else
                if ~istable(Results)
                    error('Morphology results must be a table.');
                end
                
                app.MorphologyCandidates = Results;
            end
            
            % Build ONE complete suggested-candidate table
            app.buildFinalSuggestedCandidateTable();
            
            app.WorkflowStage = "DISCOVERY_READY";
            
            app.showDiscoveryFigures();
            
            nIntensity = 0;
            nPosition = 0;
            nFWHM = 0;
            
            T = app.UITable.Data;
            
            if ~isempty(T)
                
                nIntensity = nnz(T.FeatureType == "INTENSITY");
                nPosition = nnz(T.FeatureType == "POSITION");
                nFWHM = nnz(T.FeatureType == "FWHM");
            end
            
            app.setTextArea({ ...
                'SUGGESTED CANDIDATE SET COMPLETE'; ...
                '────────────────────────────────'; ...
                ''; ...
                sprintf('INTENSITY candidates : %d',nIntensity); ...
                sprintf('POSITION candidates  : %d',nPosition); ...
                sprintf('FWHM candidates      : %d',nFWHM); ...
                sprintf('TOTAL                : %d',height(T)); ...
                ''; ...
                'Status: DISCOVERY READY'; ...
                ''; ...
                'All rows are suggestions only.'; ...
                'Select a candidate and press Validate candidate.'; ...
                'Final confirmation remains a human decision.' ...
                });
        end
    end


    methods (Access = private)
        
        function S = defaultAnalysisSettings(app)


            % ================================================================
            % CENTRAL ANALYSIS SETTINGS
            %
            % Every threshold used by the app must come from this structure.
            %
            % These are study screening criteria.
            % They are NOT universal Raman biomarker thresholds.
            % ================================================================
            
            S = struct();
            
            
            % ================================================================
            % VERSION
            % ================================================================
            
            S.Version = ...
                "CandidateConfirm-20260819";
            
            
            S.RandomSeed = ...
                17082026;
            
            
            % ================================================================
            % SPECTRAL RANGES
            % ================================================================
            
            S.Range = struct();
            
            
            S.Range.Analysis = ...
                [400 3100];
            
            
            S.Range.Fingerprint = ...
                [400 1800];
            
            
            S.Range.HighWN = ...
                [2800 3100];
            
            
            % ================================================================
            % NORMALIZATION
            %
            % L2 normalization is calculated ONCE using the whole
            % 400-3100 cm^-1 analysis range.
            % ================================================================
            
            S.Normalization = struct();
            
            
            S.Normalization.Method = ...
                "Spectrum-wise L2 vector normalization";
            
            
            S.Normalization.Range = ...
                [400 3100];
            
            
            % ================================================================
            % DISCOVERY
            %
            % Discovery is intentionally permissive.
            % It proposes candidates; it does NOT validate them.
            %
            % Tier A = high-priority discovery
            % Tier B = standard candidate
            % Tier C = exploratory candidate
            % ================================================================
            
            S.Discovery = struct();
            
            S.Discovery.Smooth_cm1 = 12;
            
            % Broad screening gate
            S.Discovery.DifferencePercentile = 60;
            S.Discovery.RobustEffectThreshold = 0.30;
            
            % Tier B
            S.Discovery.TierB_DifferencePercentile = 70;
            S.Discovery.TierB_RobustEffectThreshold = 0.50;
            
            % Tier A
            S.Discovery.TierA_DifferencePercentile = 80;
            S.Discovery.TierA_RobustEffectThreshold = 0.80;
            
            S.Discovery.MaxGapToBridge_cm1 = 8;
            S.Discovery.MinimumROIWidth_cm1 = 6;
            S.Discovery.SharpFeatureWidth_cm1 = 25;
            
            % ================================================================
            % SHARED VALIDATION
            % ================================================================
            
            S.Shared = struct();
            
            
            S.Shared.MinEffectNorm = ...
                0.80;
            
            
            S.Shared.MaxRelativeIQR = ...
                0.50;
            
            
            S.Shared.MinBootstrapDirectionStability = ...
                0.90;
            
            
            S.Shared.NBootstrap = ...
                1000;
            
            
            S.Shared.PCALoadingPercentile = ...
                90;
            
            
            S.Shared.DisplayHalfWindow_cm1 = ...
                30;
            
            
            % ================================================================
            % SHIFT VALIDATION
            % ================================================================
            
            S.Shift = struct();
            
            
            S.Shift.MinPeakPrevalenceBothGroups = ...
                0.50;
            
            
            S.Shift.MinDetectedPeaksPerGroup = ...
                30;
            
            
            S.Shift.MinAbsoluteShift_cm1 = ...
                2.0;
            
            
            S.Shift.MaxPositionIQR_cm1 = ...
                5.0;
            
            
            S.Shift.NBootstrap = ...
                2000;
            
            
            S.Shift.MinBootstrapDirectionStability = ...
                0.95;
            
            
            S.Shift.PrimarySNRThreshold = ...
                2.0;
            
            
            S.Shift.ShapeHalfWindow_cm1 = ...
                12;
            
            
            S.Shift.SlopeNeighbourPoints = ...
                2;
            
            
            S.Shift.MinDirectionalFraction = ...
                0.60;
            
            
            S.Shift.LoadingStrongPercentile = ...
                90;
            
            
            S.Shift.LoadingModeratePercentile = ...
                70;
            
            
            S.Shift.DifferenceSupportHalfWindow_cm1 = ...
                4;
            
            
            S.Shift.DisplayPadding_cm1 = ...
                15;
            
            % ================================================================
            % FWHM / WIDTH VALIDATION
            %
            % Mean-spectrum FWHM is used ONLY for morphology screening.
            % Validation is performed on individual spectra.
            % ================================================================
            
            S.Width = struct();
            
            S.Width.MinPeakPrevalenceBothGroups = 0.50;
            S.Width.MinDetectedPeaksPerGroup = 30;
            
            S.Width.MinRobustEffect = 0.80;
            S.Width.MaxRelativeIQR = 0.50;
            
            S.Width.NBootstrap = 2000;
            S.Width.MinBootstrapDirectionStability = 0.95;
            
            S.Width.MinNormUnnormDetectionAgreement = 0.80;
            
            S.Width.PrimarySNRThreshold = 2.0;
            S.Width.ShapeHalfWindow_cm1 = 12;
            S.Width.MinPeakDistance_cm1 = 5;
            
            S.Width.DisplayPadding_cm1 = 25;
            
            
            % ================================================================
            % OUTPUT
            % ================================================================
            
            S.Output = struct();
            
            
            S.Output.ExcelName = ...
                "validate.xlsx";
            
            
            S.Output.AnalysisCheckpointName = ...
                "candidate_analysis_session.mat";
            
            
            S.Output.EvidenceFolder = ...
                "Evidence";
            
            
            S.Output.DiscoveryFolder = ...
                "Discovery";
            
            
            S.Output.FigureResolution = ...
                300;
            
            
            % ================================================================
            % INTERPRETATION NOTE
            % ================================================================
            
            S.Interpretation = ...
                "Evidence score is a count of predefined evidence criteria, not biomarker probability.";
            
        end
        
        
        function updateWorkflowControls(app)
            app.LoaddataButton.Enable = 'on';
            app.DiscovercandidatesButton.Enable = 'on';
            app.AddcandidatesButton.Enable = 'on';
            app.ValidatecandidateButton.Enable = 'on';
            app.ConfirmcandidatesButton.Enable = 'on';
            app.ExcelexportButton.Enable = 'on';
            app.FinishButton.Enable = 'on';
        end


        function tf = hasAnyReviewedCandidate(app)
            
            tf = ...
                false;
            
            
            if isempty(app.CandidateResults)
                
                return
                
            end
            
            
            for k = 1:numel(app.CandidateResults)
                
                R = ...
                    app.CandidateResults(k);
                
                
                if isfield(R,'Decision')
                    
                    decision = ...
                        string(R.Decision);
                    
                    
                    if decision == "CONFIRMED" || ...
                            decision == "REJECTED"
                        
                        tf = ...
                            true;
                        
                        return
                        
                    end
                    
                end
                
            end
            
        end


        function setTextArea(app,lines)
            
            % ================================================================
            % SAFE TEXTAREA WRITER
            % ================================================================
            
            if isstring(lines)
                
                lines = ...
                    cellstr(lines(:));
                
                
            elseif ischar(lines)
                
                lines = ...
                    {lines};
                
                
            elseif iscell(lines)
                
                % already acceptable
                
                
            else
                
                lines = ...
                    cellstr(string(lines(:)));
                
            end
            
            
            app.TextArea.Value = ...
                lines;
            
            
            drawnow;
            
        end
        
        
        function clearValidationAxes(app)
            
            % ================================================================
            % FOUR AXES RESERVED FOR VALIDATION
            % ================================================================
            
            axList = { ...
                app.UIAxes, ...
                app.UIAxes_2, ...
                app.UIAxes_3, ...
                app.UIAxes_4};
            
            titles = { ...
                'Validation evidence 1', ...
                'Validation evidence 2', ...
                'Validation evidence 3', ...
                'Validation evidence 4'};
            
            for k = 1:4
                
                cla(axList{k},'reset');
                
                axList{k}.Box = 'on';
                
                title(axList{k},titles{k});
                
                xlabel(axList{k},'');
                
                ylabel(axList{k},'');
                
                grid(axList{k},'off');
                
            end
            
        end


        function tf = requireLoadedData(app)
            
            tf = ...
                false;
            
            
            if isempty(app.AnalysisData) || ...
                    ~isfield(app.AnalysisData,'Processed')
                
                uialert( ...
                    app.UIFigure, ...
                    [ ...
                    'No analysis dataset is loaded.' newline newline ...
                    'Use "Load data" first, or restore a saved analysis session.' ...
                    ], ...
                    'Data required', ...
                    'Icon','warning');
                
                return
                
            end
            
            
            tf = ...
                true;
            
        end


        function tf = requireDiscovery(app)
            
            tf = false;
            
            hasPCA = ...
                isstruct(app.PCA_Full) && ...
                isfield(app.PCA_Full,'Scores');
            
            hasCandidateTable = ...
                istable(app.UITable.Data) && ...
                ~isempty(app.UITable.Data);
            
            if isempty(app.X_norm) || ...
                    ~hasPCA || ...
                    ~hasCandidateTable
                
                uialert( ...
                    app.UIFigure, ...
                    [ ...
                    'The complete suggested candidate set is not available.' newline newline ...
                    'Run Discover candidates and finish morphology screening first.' ...
                    ], ...
                    'Discovery required', ...
                    'Icon','warning');
                
                return
            end
            
            tf = true;
        end
        
        
        function I = evaluateShiftPCARegion( ...
                app, ...
                centerWN, ...
                regionLow, ...
                regionHigh)
            
            % ================================================================
            % EXACT SHIFT PCA INTERPRETATION
            %
            % PCASupport =
            %     STRONG
            %     OR MODERATE
            %     OR local loading extremum inside search region
            % ================================================================
            
            if centerWN >= 400 && ...
                    centerWN <= 1800
                
                P = app.PCA_Fingerprint;
                
                domain = "Fingerprint";
                
            elseif centerWN >= 2800 && ...
                    centerWN <= 3100
                
                P = app.PCA_HighWN;
                
                domain = "High-WN";
                
            else
                
                P = app.PCA_Full;
                
                domain = "Full";
                
            end
            
            
            gid = ...
                string(P.GroupID(:));
            
            
            idxA = ...
                gid == app.groupA;
            
            
            idxB = ...
                gid == app.groupB;
            
            
            nPC = ...
                min( ...
                3, ...
                size(P.Scores,2));
            
            
            if nPC < 2
                
                error( ...
                    'Selected PCA model contains fewer than 2 PCs.');
                
            end
            
            
            scoreEffects = ...
                NaN(nPC,1);
            
            
            for pc = 1:nPC
                
                scoreEffects(pc) = ...
                    app.robustEffectLocal( ...
                    P.Scores(idxA,pc), ...
                    P.Scores(idxB,pc));
                
            end
            
            
            [~,orderPC] = ...
                sort( ...
                abs(scoreEffects), ...
                'descend');
            
            
            bestPC = ...
                orderPC(1);
            
            
            secondPC = ...
                orderPC(2);
            
            
            loadingCurve = ...
                double( ...
                P.Loadings(:,bestPC));
            
            
            pcaWN = ...
                double( ...
                P.Wavenumber(:));
            
            
            loadingSearchMask = ...
                pcaWN >= regionLow & ...
                pcaWN <= regionHigh;
            
            
            loadingAbs = ...
                abs(loadingCurve);
            
            
            thresholdStrong = ...
                prctile( ...
                loadingAbs, ...
                app.Settings.Shift.LoadingStrongPercentile);
            
            
            thresholdModerate = ...
                prctile( ...
                loadingAbs, ...
                app.Settings.Shift.LoadingModeratePercentile);
            
            
            if any(loadingSearchMask)
                
                localLoadingAbs = ...
                    loadingAbs(loadingSearchMask);
                
                
                localLoadingWN = ...
                    pcaWN(loadingSearchMask);
                
                
                [maxLoadingRegion,idxMaxLoad] = ...
                    max(localLoadingAbs);
                
                
                loadingExtremumWN = ...
                    localLoadingWN(idxMaxLoad);
                
                
                if maxLoadingRegion >= ...
                        thresholdStrong
                    
                    PCALoadingLevel = ...
                        "STRONG";
                    
                elseif maxLoadingRegion >= ...
                        thresholdModerate
                    
                    PCALoadingLevel = ...
                        "MODERATE";
                    
                else
                    
                    PCALoadingLevel = ...
                        "WEAK";
                    
                end
                
                
                localMaxMask = ...
                    islocalmax( ...
                    loadingAbs, ...
                    'FlatSelection','center');
                
                
                PCALocalExtremumInSearch = ...
                    any( ...
                    localMaxMask & ...
                    loadingSearchMask);
                
            else
                
                maxLoadingRegion = ...
                    NaN;
                
                
                loadingExtremumWN = ...
                    NaN;
                
                
                PCALoadingLevel = ...
                    "NONE";
                
                
                PCALocalExtremumInSearch = ...
                    false;
                
            end
            
            
            PCASupport = ...
                PCALoadingLevel == "STRONG" || ...
                PCALoadingLevel == "MODERATE" || ...
                PCALocalExtremumInSearch;
            
            
            I = struct();
            
            I.Model = P;
            
            I.Domain = domain;
            
            I.BestPC = bestPC;
            
            I.SecondPC = secondPC;
            
            I.ScoreEffects = scoreEffects;
            
            I.ScoreEffect = ...
                scoreEffects(bestPC);
            
            I.LoadingCurve = ...
                loadingCurve;
            
            I.LoadingLevel = ...
                PCALoadingLevel;
            
            I.LoadingExtremumWN = ...
                loadingExtremumWN;
            
            I.MaxLoading = ...
                maxLoadingRegion;
            
            I.LocalExtremum = ...
                PCALocalExtremumInSearch;
            
            I.Support = ...
                logical(PCASupport);
            
        end
        
        
        function tf = requireSelectedCandidate(app)
            
            tf = ...
                false;
            
            
            if ~isfinite(app.SelectedCandidateRow) || ...
                    app.SelectedCandidateID == ""
                
                uialert( ...
                    app.UIFigure, ...
                    [ ...
                    'No candidate is selected.' newline newline ...
                    'Select a row in the candidate table first.' ...
                    ], ...
                    'Candidate required', ...
                    'Icon','warning');
                
                return
                
            end
            
            
            tf = ...
                true;
            
        end


        function tf = requireCurrentValidation(app)
            
            tf = ...
                false;
            
            
            if ~app.HasCurrentValidation || ...
                    isempty(app.CurrentValidation)
                
                uialert( ...
                    app.UIFigure, ...
                    [ ...
                    'The selected candidate has not been validated yet.' newline newline ...
                    'Use "Validate candidate" first.' ...
                    ], ...
                    'Validation required', ...
                    'Icon','warning');
                
                return
                
            end
            
            
            tf = ...
                true;
            
        end


        function P = prepareSingleHomeSession(app,sessionFile)
            
            %#ok<INUSD>
            
            
            if ~isfile(sessionFile)
                
                error( ...
                    'Session file does not exist:\n%s', ...
                    sessionFile);
                
            end
            
            
            % ================================================================
            % LOAD STRUCT S
            % ================================================================
            
            tmp = ...
                load( ...
                sessionFile, ...
                'S');
            
            
            if ~isfield(tmp,'S')
                
                error( ...
                    ['Selected MAT does not contain RamanCube session ' ...
                    'structure S.']);
                
            end
            
            
            S = ...
                tmp.S;
            
            
            clear tmp
            
            
            % ================================================================
            % REQUIRED SESSION COMPONENTS
            % ================================================================
            
            requiredFields = ...
                [ ...
                "wn"
                "spectra"
                "rawSpectra"
                "names"
                "rawNames"
                "singleFileTable"
                ];
            
            
            for k = 1:numel(requiredFields)
                
                if ~isfield( ...
                        S, ...
                        requiredFields(k))
                    
                    error( ...
                        'Required session field missing: S.%s', ...
                        requiredFields(k));
                    
                end
                
            end
            
            
            if isempty(S.wn)
                error('S.wn is empty.');
            end
            
            
            if isempty(S.spectra)
                error('S.spectra is empty.');
            end
            
            
            if isempty(S.rawSpectra)
                error('S.rawSpectra is empty.');
            end
            
            
            if isempty(S.names)
                error('S.names is empty.');
            end
            
            
            if isempty(S.singleFileTable)
                error('S.singleFileTable is empty.');
            end
            
            
            % ================================================================
            % FINAL PROCESSED POPULATION
            % ================================================================
            
            wn = ...
                double(S.wn(:));
            
            
            Xproc = ...
                double(S.spectra);
            
            
            specNames = ...
                app.cleanSpectrumNames( ...
                S.names);
            
            
            nWN = ...
                numel(wn);
            
            
            nProc = ...
                size(Xproc,2);
            
            
            if size(Xproc,1) ~= nWN
                
                error( ...
                    'Processed matrix rows do not match S.wn.');
                
            end
            
            
            if numel(specNames) ~= nProc
                
                error( ...
                    'Processed names do not match processed spectra.');
                
            end
            
            
            if any(~isfinite(wn))
                
                error( ...
                    'S.wn contains NaN or Inf.');
                
            end
            
            
            if any(~isfinite(Xproc(:)))
                
                error( ...
                    'Processed spectra contain NaN or Inf.');
                
            end
            
            
            if numel(unique(specNames)) ~= ...
                    numel(specNames)
                
                error( ...
                    ['Processed spectrum names are not unique. ' ...
                    'Safe provenance matching is impossible.']);
                
            end
            
            
            % ================================================================
            % WN DIRECTION
            % ================================================================
            
            dwn = ...
                diff(wn);
            
            
            flipWN = ...
                false;
            
            
            if all(dwn > 0)
                
                % already ascending
                
            elseif all(dwn < 0)
                
                wn = ...
                    flipud(wn);
                
                
                Xproc = ...
                    flipud(Xproc);
                
                
                flipWN = ...
                    true;
                
            else
                
                error( ...
                    'Wavenumber axis is non-monotonic.');
                
            end
            
            
            if numel(unique(wn)) ~= ...
                    numel(wn)
                
                error( ...
                    'Wavenumber axis contains duplicates.');
                
            end
            
            
            % ================================================================
            % ORIGINAL FILE TABLE
            % ================================================================
            
            FT = ...
                S.singleFileTable;
            
            
            if ~istable(FT)
                
                error( ...
                    'S.singleFileTable is not a table.');
                
            end
            
            
            requiredColumns = ...
                [ ...
                "SpectrumName"
                "GroupID"
                "GroupLabel"
                "FilePath"
                ];
            
            
            vars = ...
                string( ...
                FT.Properties.VariableNames);
            
            
            missing = ...
                requiredColumns( ...
                ~ismember( ...
                requiredColumns, ...
                vars));
            
            
            if ~isempty(missing)
                
                error( ...
                    'singleFileTable missing: %s', ...
                    strjoin(missing,', '));
                
            end
            
            
            originalNames = ...
                app.cleanSpectrumNames( ...
                FT.SpectrumName);
            
            
            if numel(unique(originalNames)) ~= ...
                    numel(originalNames)
                
                error( ...
                    ['Original SpectrumName values are not unique. ' ...
                    'Cannot safely reconstruct provenance.']);
                
            end
            
            
            nOriginal = ...
                height(FT);
            
            
            % ================================================================
            % PROCESSED -> ORIGINAL METADATA
            % ================================================================
            
            [foundProcessed,finalLocation] = ...
                ismember( ...
                specNames, ...
                originalNames);
            
            
            if ~all(foundProcessed)
                
                missingNames = ...
                    specNames(~foundProcessed);
                
                
                error( ...
                    ['Some processed spectra cannot be mapped to ' ...
                    'singleFileTable:\n%s'], ...
                    strjoin(missingNames,newline));
                
            end
            
            
            if numel(unique(finalLocation)) ~= ...
                    nProc
                
                error( ...
                    'Processed-to-original mapping is not one-to-one.');
                
            end
            
            
            retainedMeta = ...
                FT(finalLocation,:);
            
            
            retainedMeta.OriginalIndex = ...
                finalLocation(:);
            
            
            retainedMeta.ProcessedIndex = ...
                (1:nProc)';
            
            
            groupID = ...
                string( ...
                retainedMeta.GroupID(:));
            
            
            groupLabel = ...
                string( ...
                retainedMeta.GroupLabel(:));
            
            
            % ================================================================
            % REMOVED SPECTRA
            % ================================================================
            
            removedMask = ...
                true(nOriginal,1);
            
            
            removedMask(finalLocation) = ...
                false;
            
            
            removedMeta = ...
                FT(removedMask,:);
            
            
            removedMeta.OriginalIndex = ...
                find(removedMask);
            
            
            % ================================================================
            % RAW IDENTITY RECONSTRUCTION
            % ================================================================
            
            XrawOriginal = ...
                double( ...
                S.rawSpectra);
            
            
            if flipWN && ...
                    size(XrawOriginal,1) == nWN
                
                XrawOriginal = ...
                    flipud(XrawOriginal);
                
            end
            
            
            nRaw = ...
                size(XrawOriginal,2);
            
            
            if ~isempty(S.rawNames) && ...
                    numel(S.rawNames) == nRaw
                
                rawNames = ...
                    app.cleanSpectrumNames( ...
                    S.rawNames);
                
                
                rawIdentitySource = ...
                    "S.rawNames";
                
                
            elseif nRaw == nOriginal
                
                rawNames = ...
                    originalNames;
                
                
                rawIdentitySource = ...
                    "singleFileTable order - dimension verified";
                
                
            else
                
                error( ...
                    ['RAW spectrum identities cannot be reconstructed safely.' ...
                    newline ...
                    'Raw columns: %d' newline ...
                    'Original metadata rows: %d'], ...
                    nRaw, ...
                    nOriginal);
                
            end
            
            
            if numel(unique(rawNames)) ~= ...
                    numel(rawNames)
                
                error( ...
                    'RAW spectrum names are not unique.');
                
            end
            
            
            [rawFound,rawLocation] = ...
                ismember( ...
                specNames, ...
                rawNames);
            
            
            if ~all(rawFound)
                
                error( ...
                    'Not every processed spectrum has a RAW counterpart.');
                
            end
            
            
            if numel(unique(rawLocation)) ~= ...
                    nProc
                
                error( ...
                    'RAW-to-processed mapping is not one-to-one.');
                
            end
            
            
            Xraw = ...
                XrawOriginal(:,rawLocation);
            
            
            if ~all( ...
                    rawNames(rawLocation) == ...
                    specNames)
                
                error( ...
                    'Internal RAW/processed identity mapping failed.');
                
            end
            
            
            % ================================================================
            % GROUP SUMMARY
            % ================================================================
            
            groupNames = ...
                unique( ...
                groupID, ...
                'stable');
            
            
            nGroups = ...
                numel(groupNames);
            
            
            groupCount = ...
                zeros(nGroups,1);
            
            
            groupIndex = ...
                cell(nGroups,1);
            
            
            for g = 1:nGroups
                
                groupIndex{g} = ...
                    find( ...
                    groupID == ...
                    groupNames(g));
                
                
                groupCount(g) = ...
                    numel( ...
                    groupIndex{g});
                
            end
            
            
            groupSummary = ...
                table( ...
                (1:nGroups)', ...
                groupNames, ...
                groupCount, ...
                'VariableNames', ...
                { ...
                'GroupNumber', ...
                'GroupID', ...
                'SpectrumCount' ...
                });
            
            
            % ================================================================
            % RESTRICT ONLY TO MAIN ANALYSIS RANGE
            % ================================================================
            
            mainMask = ...
                wn >= app.Settings.Range.Analysis(1) & ...
                wn <= app.Settings.Range.Analysis(2);
            
            
            if ~any(mainMask)
                
                error( ...
                    'No points exist in the 400-3100 cm^-1 range.');
                
            end
            
            
            wnAnalysis = ...
                wn(mainMask);
            
            
            XprocAnalysis = ...
                Xproc(mainMask,:);
            
            
            if size(Xraw,1) == numel(wn)
                
                XrawAnalysis = ...
                    Xraw(mainMask,:);
                
                rawWNCompatible = ...
                    true;
                
            else
                
                XrawAnalysis = ...
                    [];
                
                rawWNCompatible = ...
                    false;
                
            end
            
            
            % ================================================================
            % OUTPUT
            % ================================================================
            
            P = ...
                struct();
            
            
            P.SessionFile = ...
                string(sessionFile);
            
            
            P.Wavenumber = ...
                wnAnalysis;
            
            
            P.Processed = ...
                XprocAnalysis;
            
            
            P.Raw = ...
                XrawAnalysis;
            
            
            P.SpectrumNames = ...
                specNames;
            
            
            P.GroupID = ...
                groupID;
            
            
            P.GroupLabel = ...
                groupLabel;
            
            
            P.GroupNames = ...
                groupNames;
            
            
            P.GroupIndex = ...
                groupIndex;
            
            
            P.GroupSummary = ...
                groupSummary;
            
            
            P.RetainedMetadata = ...
                retainedMeta;
            
            
            P.RemovedMetadata = ...
                removedMeta;
            
            
            P.RawIdentitySource = ...
                rawIdentitySource;
            
            
            P.RawWNCompatible = ...
                rawWNCompatible;
            
            
            if isfield(S,'processHistory')
                
                P.ProcessHistory = ...
                    string(S.processHistory);
                
            else
                
                P.ProcessHistory = ...
                    "";
                
            end
            
            
            P.OriginalSpectrumCount = ...
                nOriginal;
            
            
            P.FinalSpectrumCount = ...
                nProc;
            
            
            P.RemovedSpectrumCount = ...
                height(removedMeta);
            
            
            P.PreparedAt = ...
                string( ...
                datetime( ...
                'now', ...
                'Format','yyyy-MM-dd HH:mm:ss'));
            
        end


        function namesOut = cleanSpectrumNames(app,namesIn)
            
            %#ok<INUSD>
            
            
            namesOut = ...
                strtrim( ...
                string( ...
                namesIn(:)));
            
            
            namesOut = ...
                regexprep( ...
                namesOut, ...
                '\.(txt|mat|csv)$', ...
                '', ...
                'ignorecase');
            
        end


        function A = buildTwoAnalysisGroups(app,P)
            
            originalGroupID = ...
                string(P.GroupID(:));
            
            
            originalGroupNames = ...
                unique( ...
                originalGroupID, ...
                'stable');
            
            
            if numel(originalGroupNames) < 2
                
                error( ...
                    'At least two original groups are required.');
                
            end
            
            
            % ================================================================
            % DISPLAY LIST
            % ================================================================
            
            listText = ...
                strings( ...
                numel(originalGroupNames), ...
                1);
            
            
            for g = 1:numel(originalGroupNames)
                
                n = ...
                    nnz( ...
                    originalGroupID == ...
                    originalGroupNames(g));
                
                
                listText(g) = ...
                    sprintf( ...
                    'G%d | %s | n=%d', ...
                    g, ...
                    originalGroupNames(g), ...
                    n);
                
            end
            
            
            namesNew = ...
                strings(2,1);
            
            
            selectedOriginal = ...
                cell(2,1);
            
            
            used = ...
                false( ...
                numel(originalGroupNames), ...
                1);
            
            
            % ================================================================
            % GROUP A / B
            % ================================================================
            
            for newG = 1:2
                
                if newG == 1
                    
                    defaultName = ...
                        'Group_A';
                    
                    role = ...
                        'GROUP A';
                    
                else
                    
                    defaultName = ...
                        'Group_B';
                    
                    role = ...
                        'GROUP B';
                    
                end
                
                
                a = ...
                    inputdlg( ...
                    {sprintf('Name for %s:',role)}, ...
                    sprintf('Define %s',role), ...
                    [1 55], ...
                    {defaultName});
                
                
                if isempty(a)
                    
                    error( ...
                        'Group creation cancelled.');
                    
                end
                
                
                namesNew(newG) = ...
                    strtrim( ...
                    string(a{1}));
                
                
                if namesNew(newG) == ""
                    
                    error( ...
                        'Analysis group name cannot be empty.');
                    
                end
                
                
                if newG == 2 && ...
                        namesNew(2) == namesNew(1)
                    
                    error( ...
                        'Group A and Group B must have different names.');
                    
                end
                
                
                availableIdx = ...
                    find(~used);
                
                
                [sel,ok] = ...
                    listdlg( ...
                    'PromptString', ...
                    sprintf( ...
                    'Select original groups for %s:', ...
                    role), ...
                    'SelectionMode','multiple', ...
                    'ListString', ...
                    cellstr( ...
                    listText(availableIdx)), ...
                    'ListSize', ...
                    [620 300]);
                
                
                if ~ok || ...
                        isempty(sel)
                    
                    error( ...
                        'No original group selected for %s.', ...
                        role);
                    
                end
                
                
                idx = ...
                    availableIdx(sel);
                
                
                selectedOriginal{newG} = ...
                    originalGroupNames(idx);
                
                
                used(idx) = ...
                    true;
                
            end
            
            
            % ================================================================
            % UNUSED GROUPS
            % ================================================================
            
            unused = ...
                originalGroupNames(~used);
            
            
            if ~isempty(unused)
                
                q = ...
                    questdlg( ...
                    sprintf( ...
                    [ ...
                    'These original groups were not assigned:\n\n%s\n\n' ...
                    'Exclude them from this comparison?' ...
                    ], ...
                    strjoin(unused,newline)), ...
                    'Unused original groups', ...
                    'Exclude', ...
                    'Cancel', ...
                    'Cancel');
                
                
                if ~strcmp(q,'Exclude')
                    
                    error( ...
                        'Group creation cancelled.');
                    
                end
                
            end
            
            
            % ================================================================
            % FINAL GROUP VECTOR
            % ================================================================
            
            analysisGroupID_all = ...
                strings( ...
                numel(originalGroupID), ...
                1);
            
            
            for g = 1:2
                
                mask = ...
                    ismember( ...
                    originalGroupID, ...
                    string( ...
                    selectedOriginal{g}));
                
                
                analysisGroupID_all(mask) = ...
                    namesNew(g);
                
            end
            
            
            includedMask = ...
                analysisGroupID_all ~= "";
            
            
            analysisGroupID = ...
                analysisGroupID_all(includedMask);
            
            
            Xanalysis = ...
                P.Processed(:,includedMask);
            
            
            specNames = ...
                P.SpectrumNames(includedMask);
            
            
            originalID = ...
                originalGroupID(includedMask);
            
            
            groupIndex = ...
                cell(2,1);
            
            
            groupNumber = ...
                zeros( ...
                numel(analysisGroupID), ...
                1);
            
            
            for g = 1:2
                
                groupIndex{g} = ...
                    find( ...
                    analysisGroupID == ...
                    namesNew(g));
                
                
                if numel(groupIndex{g}) < 2
                    
                    error( ...
                        'Analysis group %s contains fewer than two spectra.', ...
                        namesNew(g));
                    
                end
                
                
                groupNumber( ...
                    groupIndex{g}) = ...
                    g;
                
            end
            
            
            % ================================================================
            % MAP TABLE
            % ================================================================
            
            OriginalGroup = ...
                strings(0,1);
            
            
            AnalysisGroup = ...
                strings(0,1);
            
            
            for g = 1:2
                
                oo = ...
                    string( ...
                    selectedOriginal{g}(:));
                
                
                OriginalGroup = ...
                    [OriginalGroup;oo]; %#ok<AGROW>
                
                
                AnalysisGroup = ...
                    [ ...
                    AnalysisGroup; ...
                    repmat( ...
                    namesNew(g), ...
                    numel(oo), ...
                    1) ...
                    ]; %#ok<AGROW>
                
            end
            
            
            groupMap = ...
                table( ...
                OriginalGroup, ...
                AnalysisGroup);
            
            
            % ================================================================
            % FINAL CONFIRMATION
            % ================================================================
            
            q = ...
                questdlg( ...
                sprintf( ...
                [ ...
                'Group A: %s | n=%d\n' ...
                'Group B: %s | n=%d\n' ...
                'Excluded spectra: %d\n\n' ...
                'Use this grouping?' ...
                ], ...
                namesNew(1), ...
                numel(groupIndex{1}), ...
                namesNew(2), ...
                numel(groupIndex{2}), ...
                nnz(~includedMask)), ...
                'Confirm analysis groups', ...
                'Confirm', ...
                'Cancel', ...
                'Confirm');
            
            
            if ~strcmp(q,'Confirm')
                
                error( ...
                    'Grouping was not confirmed.');
                
            end
            
            
            % ================================================================
            % STRUCT
            % ================================================================
            
            A = ...
                struct();
            
            
            A.Wavenumber = ...
                P.Wavenumber;
            
            
            A.Processed = ...
                Xanalysis;
            
            
            A.SpectrumNames = ...
                specNames;
            
            
            A.OriginalGroupID = ...
                originalID;
            
            
            A.GroupID = ...
                analysisGroupID;
            
            
            A.GroupNumber = ...
                groupNumber;
            
            
            A.GroupNames = ...
                namesNew;
            
            
            A.GroupIndex = ...
                groupIndex;
            
            
            A.GroupMap = ...
                groupMap;
            
            
            if height(P.RetainedMetadata) == ...
                    numel(P.GroupID)
                
                A.RetainedMetadata = ...
                    P.RetainedMetadata( ...
                    includedMask,:);
                
            end
            
            
            A.CreatedAt = ...
                string( ...
                datetime( ...
                'now', ...
                'Format','yyyy-MM-dd HH:mm:ss'));
            
        end


        function cacheValidationResult(app,R)
            
            if isempty(R) || ~isstruct(R)
                return
            end
            
            if ~isfield(R,'CandidateID') || ~isfield(R,'Mode')
                error('Validation result requires CandidateID and Mode.');
            end
            
            E = struct( ...
                'CandidateID',string(R.CandidateID), ...
                'Mode',string(R.Mode), ...
                'Result',R);
            
            idx = [];
            
            for k = 1:numel(app.ValidationCache)
                if string(app.ValidationCache(k).CandidateID) == ...
                        string(E.CandidateID)
                    idx = k;
                    break
                end
            end
            
            if isempty(idx)
                app.ValidationCache(end+1) = E;
            else
                app.ValidationCache(idx) = E;
            end
        end
        
        
        function [tf,R] = getCachedValidation(app,candidateID)
            
            tf = false;
            R = struct();
            
            if isempty(app.ValidationCache)
                return
            end
            
            candidateID = string(candidateID);
            
            for k = 1:numel(app.ValidationCache)
                
                if string(app.ValidationCache(k).CandidateID) == candidateID
                    tf = true;
                    R = app.ValidationCache(k).Result;
                    return
                end
            end
        end


        function showLoadedDataSummary(app)
            
            P = ...
                app.PreparedData;
            
            
            A = ...
                app.AnalysisData;
            
            
            nA = ...
                nnz( ...
                string(A.GroupID) == ...
                app.groupA);
            
            
            nB = ...
                nnz( ...
                string(A.GroupID) == ...
                app.groupB);
            
            
            lines = ...
                { ...
                'DATA LOADED'; ...
                '────────────────────────────────'; ...
                ''; ...
                ['Session: ' char(app.SessionFile)]; ...
                ''; ...
                sprintf( ...
                'Original spectra: %d', ...
                P.OriginalSpectrumCount); ...
                sprintf( ...
                'Final processed spectra: %d', ...
                P.FinalSpectrumCount); ...
                sprintf( ...
                'Removed spectra: %d', ...
                P.RemovedSpectrumCount); ...
                sprintf( ...
                'WN range: %.2f - %.2f cm^-1', ...
                min(A.Wavenumber), ...
                max(A.Wavenumber)); ...
                ''; ...
                'PROVENANCE'; ...
                '────────────────────────────────'; ...
                '[PASS] Processed matrix integrity'; ...
                '[PASS] Unique processed SpectrumName'; ...
                '[PASS] Processed -> metadata mapping'; ...
                '[PASS] RAW -> processed identity mapping'; ...
                ['RAW identity source: ' char(P.RawIdentitySource)]; ...
                ''; ...
                'ANALYSIS GROUPS'; ...
                '────────────────────────────────'; ...
                sprintf( ...
                'Group A: %s | n=%d', ...
                app.groupA,nA); ...
                sprintf( ...
                'Group B: %s | n=%d', ...
                app.groupB,nB); ...
                ''; ...
                'ANALYSIS SETTINGS'; ...
                '────────────────────────────────'; ...
                sprintf( ...
                'Normalization: %s', ...
                app.Settings.Normalization.Method); ...
                sprintf( ...
                'Normalization range: %.0f-%.0f cm^-1', ...
                app.Settings.Normalization.Range); ...
                sprintf( ...
                'Fingerprint: %.0f-%.0f cm^-1', ...
                app.Settings.Range.Fingerprint); ...
                sprintf( ...
                'High-WN: %.0f-%.0f cm^-1', ...
                app.Settings.Range.HighWN); ...
                ''; ...
                'DISCOVERY SETTINGS'; ...
                '────────────────────────────────'; ...
                sprintf( ...
                'Smoothing: %.1f cm^-1', ...
                app.Settings.Discovery.Smooth_cm1); ...
                sprintf( ...
                'Difference percentile: %.1f', ...
                app.Settings.Discovery.DifferencePercentile); ...
                sprintf( ...
                'Robust effect threshold: %.2f', ...
                app.Settings.Discovery.RobustEffectThreshold); ...
                sprintf( ...
                'Max bridge gap: %.1f cm^-1', ...
                app.Settings.Discovery.MaxGapToBridge_cm1); ...
                sprintf( ...
                'Minimum ROI width: %.1f cm^-1', ...
                app.Settings.Discovery.MinimumROIWidth_cm1) ...
                };
            
            
            app.setTextArea(lines);
            
        end


        function runDiscovery(app)
            
            A = ...
                app.AnalysisData;
            
            
            wnInput = ...
                double( ...
                A.Wavenumber(:));
            
            
            Xinput = ...
                double( ...
                A.Processed);
            
            
            spectrumNames = ...
                string( ...
                A.SpectrumNames(:));
            
            
            gid = ...
                string( ...
                A.GroupID(:));
            
            
            groups = ...
                string( ...
                A.GroupNames(:));
            
            
            if numel(groups) ~= 2
                
                error( ...
                    'Discovery requires exactly TWO analysis groups.');
                
            end
            
            
            if size(Xinput,1) ~= ...
                    numel(wnInput)
                
                error( ...
                    'Processed rows do not match Wavenumber.');
                
            end
            
            
            if size(Xinput,2) ~= ...
                    numel(gid)
                
                error( ...
                    'Processed columns do not match GroupID.');
                
            end
            
            
            if any(~isfinite(Xinput(:)))
                
                error( ...
                    'Processed spectra contain NaN or Inf.');
                
            end
            
            
            % ================================================================
            % ANALYSIS RANGE
            % ================================================================
            
            mainMask = ...
                wnInput >= ...
                app.Settings.Range.Analysis(1) & ...
                wnInput <= ...
                app.Settings.Range.Analysis(2);
            
            
            wn = ...
                wnInput(mainMask);
            
            
            X_unorm = ...
                Xinput(mainMask,:);
            
            
            gA = ...
                groups(1);
            
            
            gB = ...
                groups(2);
            
            
            ia = ...
                find(gid == gA);
            
            
            ib = ...
                find(gid == gB);
            
            
            nA = ...
                numel(ia);
            
            
            nB = ...
                numel(ib);
            
            
            if nA < 2 || nB < 2
                
                error( ...
                    'Each group requires at least 2 spectra.');
                
            end
            
            
            % ================================================================
            % WHOLE-RANGE L2 NORMALIZATION
            % ================================================================
            
            vectorNorms = ...
                sqrt( ...
                sum( ...
                X_unorm.^2, ...
                1));
            
            
            if any(~isfinite(vectorNorms)) || ...
                    any(vectorNorms <= eps)
                
                error( ...
                    'Invalid spectrum-wise vector norm.');
                
            end
            
            
            X_norm = ...
                X_unorm ./ ...
                vectorNorms;
            
            
            % ================================================================
            % GROUP MATRICES
            % ================================================================
            
            XA = ...
                X_norm(:,ia);
            
            
            XB = ...
                X_norm(:,ib);
            
            
            XAu = ...
                X_unorm(:,ia);
            
            
            XBu = ...
                X_unorm(:,ib);
            
            
            % ================================================================
            % NORMALIZED SUMMARY
            % ================================================================
            
            meanA = ...
                mean( ...
                XA,2,'omitnan');
            
            
            meanB = ...
                mean( ...
                XB,2,'omitnan');
            
            
            medianA = ...
                median( ...
                XA,2,'omitnan');
            
            
            medianB = ...
                median( ...
                XB,2,'omitnan');
            
            
            p10A = ...
                prctile(XA,10,2);
            
            p25A = ...
                prctile(XA,25,2);
            
            p75A = ...
                prctile(XA,75,2);
            
            p90A = ...
                prctile(XA,90,2);
            
            
            p10B = ...
                prctile(XB,10,2);
            
            p25B = ...
                prctile(XB,25,2);
            
            p75B = ...
                prctile(XB,75,2);
            
            p90B = ...
                prctile(XB,90,2);
            
            
            differenceMean = ...
                meanA - ...
                meanB;
            
            
            differenceMedian = ...
                medianA - ...
                medianB;
            
            
            % ================================================================
            % STANDARD EFFECT
            % ================================================================
            
            sdA = ...
                std( ...
                XA,0,2,'omitnan');
            
            
            sdB = ...
                std( ...
                XB,0,2,'omitnan');
            
            
            pooledSD = ...
                sqrt( ...
                ((nA-1).*sdA.^2 + ...
                (nB-1).*sdB.^2) ./ ...
                max(nA+nB-2,1));
            
            
            effectStandard = ...
                differenceMean ./ ...
                max(pooledSD,eps);
            
            
            % ================================================================
            % ROBUST EFFECT
            % ================================================================
            
            madA = ...
                median( ...
                abs(XA-medianA), ...
                2,'omitnan');
            
            
            madB = ...
                median( ...
                abs(XB-medianB), ...
                2,'omitnan');
            
            
            sigmaA = ...
                1.4826.*madA;
            
            
            sigmaB = ...
                1.4826.*madB;
            
            
            pooledRobust = ...
                sqrt( ...
                (sigmaA.^2 + ...
                sigmaB.^2)./2);
            
            
            effectRobust = ...
                differenceMedian ./ ...
                max(pooledRobust,eps);
            
            
            % ================================================================
            % UNNORMALIZED SENSITIVITY
            % ================================================================
            
            medianAu = ...
                median( ...
                XAu,2,'omitnan');
            
            
            medianBu = ...
                median( ...
                XBu,2,'omitnan');
            
            
            differenceMedianUn = ...
                medianAu - ...
                medianBu;
            
            
            madAu = ...
                median( ...
                abs(XAu-medianAu), ...
                2,'omitnan');
            
            
            madBu = ...
                median( ...
                abs(XBu-medianBu), ...
                2,'omitnan');
            
            
            sigmaAu = ...
                1.4826.*madAu;
            
            
            sigmaBu = ...
                1.4826.*madBu;
            
            
            pooledUn = ...
                sqrt( ...
                (sigmaAu.^2 + ...
                sigmaBu.^2)./2);
            
            
            effectRobustUn = ...
                differenceMedianUn ./ ...
                max(pooledUn,eps);
            
            
            % ================================================================
            % DISCOVERY SMOOTHING
            % ================================================================
            
            wnSpacing = ...
                median( ...
                abs(diff(wn)), ...
                'omitnan');
            
            
            smoothPoints = ...
                max( ...
                1, ...
                round( ...
                app.Settings.Discovery.Smooth_cm1 / ...
                max(wnSpacing,eps)));
            
            
            if mod(smoothPoints,2) == 0
                
                smoothPoints = ...
                    smoothPoints+1;
                
            end
            
            
            differenceMedianSmooth = ...
                movmean( ...
                differenceMedian, ...
                smoothPoints, ...
                'Endpoints','shrink');
            
            
            effectRobustSmooth = ...
                movmean( ...
                effectRobust, ...
                smoothPoints, ...
                'Endpoints','shrink');
            
            
            % ================================================================
            % DOMAIN MASKS
            % ================================================================
            
            fpMask = ...
                wn >= ...
                app.Settings.Range.Fingerprint(1) & ...
                wn <= ...
                app.Settings.Range.Fingerprint(2);
            
            
            hwMask = ...
                wn >= ...
                app.Settings.Range.HighWN(1) & ...
                wn <= ...
                app.Settings.Range.HighWN(2);
            
            
            FP = ...
                app.discoverROIForDomain( ...
                wn, ...
                fpMask, ...
                differenceMedianSmooth, ...
                effectRobustSmooth);
            
            
            HW = ...
                app.discoverROIForDomain( ...
                wn, ...
                hwMask, ...
                differenceMedianSmooth, ...
                effectRobustSmooth);
            
            
            % ================================================================
            % BUILD ROI LIST
            % ================================================================
            
            nFP = ...
                size(FP.Regions,1);
            
            
            nHW = ...
                size(HW.Regions,1);
            
            
            nROI = ...
                nFP+nHW;
            
            
            ROIID = ...
                strings(nROI,1);
            
            
            ROIDomain = ...
                strings(nROI,1);
            
            
            RegionLow = ...
                NaN(nROI,1);
            
            
            RegionHigh = ...
                NaN(nROI,1);
            
            
            row = ...
                0;
            
            
            for k = 1:nFP
                
                row = ...
                    row+1;
                
                
                ROIID(row) = ...
                    sprintf('FP%02d',k);
                
                
                ROIDomain(row) = ...
                    "Fingerprint";
                
                
                RegionLow(row) = ...
                    FP.Regions(k,1);
                
                
                RegionHigh(row) = ...
                    FP.Regions(k,2);
                
            end
            
            
            for k = 1:nHW
                
                row = ...
                    row+1;
                
                
                ROIID(row) = ...
                    sprintf('HW%02d',k);
                
                
                ROIDomain(row) = ...
                    "High-WN";
                
                
                RegionLow(row) = ...
                    HW.Regions(k,1);
                
                
                RegionHigh(row) = ...
                    HW.Regions(k,2);
                
            end
            
            
            RegionWidth = ...
                RegionHigh - ...
                RegionLow;
            
            
            % ================================================================
            % ROI METRICS
            % ================================================================
            
            RepresentativeWN = ...
                NaN(nROI,1);
            
            
            Direction = ...
                strings(nROI,1);
            
            
            MaxAbsMedianDifferenceNorm = ...
                NaN(nROI,1);
            
            
            MaxAbsRobustEffectNorm = ...
                NaN(nROI,1);
            
            
            RepresentativeRobustEffectNorm = ...
                NaN(nROI,1);
            
            
            RepresentativeRobustEffectUnnorm = ...
                NaN(nROI,1);
            
            
            NormalizationDirectionAgreement = ...
                false(nROI,1);
            
            
            PeakRobustEffect_Norm = ...
                NaN(nROI,1);
            
            
            AreaRobustEffect_Norm = ...
                NaN(nROI,1);
            
            
            SuggestedMeasure = ...
                strings(nROI,1);
            
            
            DiscoveryScore = ...
                NaN(nROI,1);
            
            DiscoveryTier = ...
                strings(nROI,1);
            
            PeakValuesNorm = ...
                NaN(size(X_norm,2),nROI);
            
            
            AreaValuesNorm = ...
                NaN(size(X_norm,2),nROI);
            
            
            PeakValuesUnnorm = ...
                NaN(size(X_norm,2),nROI);
            
            
            AreaValuesUnnorm = ...
                NaN(size(X_norm,2),nROI);
            
            
            for r = 1:nROI
                
                roiMask = ...
                    wn >= RegionLow(r) & ...
                    wn <= RegionHigh(r);
                
                
                roiIdx = ...
                    find(roiMask);
                
                
                if isempty(roiIdx)
                    
                    continue
                    
                end
                
                
                [~,li] = ...
                    max( ...
                    abs( ...
                    effectRobustSmooth(roiIdx)));
                
                
                repIdx = ...
                    roiIdx(li);
                
                
                RepresentativeWN(r) = ...
                    wn(repIdx);
                
                
                MaxAbsMedianDifferenceNorm(r) = ...
                    max( ...
                    abs( ...
                    differenceMedianSmooth(roiIdx)));
                
                
                MaxAbsRobustEffectNorm(r) = ...
                    max( ...
                    abs( ...
                    effectRobustSmooth(roiIdx)));
                
                
                RepresentativeRobustEffectNorm(r) = ...
                    effectRobust(repIdx);
                
                
                RepresentativeRobustEffectUnnorm(r) = ...
                    effectRobustUn(repIdx);
                
                
                sn = ...
                    sign( ...
                    RepresentativeRobustEffectNorm(r));
                
                
                su = ...
                    sign( ...
                    RepresentativeRobustEffectUnnorm(r));
                
                
                NormalizationDirectionAgreement(r) = ...
                    sn == su && ...
                    sn ~= 0;
                
                
                if differenceMedian(repIdx) > 0
                    
                    Direction(r) = ...
                        gA + " > " + gB;
                    
                elseif differenceMedian(repIdx) < 0
                    
                    Direction(r) = ...
                        gB + " > " + gA;
                    
                else
                    
                    Direction(r) = ...
                        "No direction";
                    
                end
                
                
                peakN = ...
                    X_norm(repIdx,:)';
                
                
                peakU = ...
                    X_unorm(repIdx,:)';
                
                
                PeakValuesNorm(:,r) = ...
                    peakN;
                
                
                PeakValuesUnnorm(:,r) = ...
                    peakU;
                
                
                PeakRobustEffect_Norm(r) = ...
                    app.robustEffectLocal( ...
                    peakN(ia), ...
                    peakN(ib));
                
                
                if numel(roiIdx) >= 2
                    
                    areaN = ...
                        trapz( ...
                        wn(roiIdx), ...
                        X_norm(roiIdx,:), ...
                        1)';
                    
                    
                    areaU = ...
                        trapz( ...
                        wn(roiIdx), ...
                        X_unorm(roiIdx,:), ...
                        1)';
                    
                else
                    
                    areaN = ...
                        peakN;
                    
                    
                    areaU = ...
                        peakU;
                    
                end
                
                
                AreaValuesNorm(:,r) = ...
                    areaN;
                
                
                AreaValuesUnnorm(:,r) = ...
                    areaU;
                
                
                AreaRobustEffect_Norm(r) = ...
                    app.robustEffectLocal( ...
                    areaN(ia), ...
                    areaN(ib));
                
                
                if RegionWidth(r) <= ...
                        app.Settings.Discovery.SharpFeatureWidth_cm1
                    
                    SuggestedMeasure(r) = ...
                        "Peak + local area";
                    
                else
                    
                    SuggestedMeasure(r) = ...
                        "Area preferred";
                    
                end
                
                
                if ROIDomain(r) == "Fingerprint"
                    dThreshold = FP.DifferenceThreshold;
                    dThresholdB = FP.TierBThreshold;
                    dThresholdA = FP.TierAThreshold;
                else
                    dThreshold = HW.DifferenceThreshold;
                    dThresholdB = HW.TierBThreshold;
                    dThresholdA = HW.TierAThreshold;
                end
                
                DiscoveryScore(r) = ...
                    MaxAbsRobustEffectNorm(r) .* ...
                    (MaxAbsMedianDifferenceNorm(r) ./ max(dThreshold,eps));
                
                if MaxAbsRobustEffectNorm(r) >= ...
                        app.Settings.Discovery.TierA_RobustEffectThreshold && ...
                        MaxAbsMedianDifferenceNorm(r) >= dThresholdA
                    
                    DiscoveryTier(r) = "A | PRIORITY";
                    
                elseif MaxAbsRobustEffectNorm(r) >= ...
                        app.Settings.Discovery.TierB_RobustEffectThreshold && ...
                        MaxAbsMedianDifferenceNorm(r) >= dThresholdB
                    
                    DiscoveryTier(r) = "B | CANDIDATE";
                    
                else
                    DiscoveryTier(r) = "C | EXPLORATORY";
                end
                
            end
            
            
            % ================================================================
            % ROI TABLE
            % ================================================================
            
            ROITable = table( ...
                ROIID, ...
                ROIDomain, ...
                RegionLow, ...
                RegionHigh, ...
                RegionWidth, ...
                RepresentativeWN, ...
                Direction, ...
                MaxAbsMedianDifferenceNorm, ...
                MaxAbsRobustEffectNorm, ...
                RepresentativeRobustEffectNorm, ...
                RepresentativeRobustEffectUnnorm, ...
                NormalizationDirectionAgreement, ...
                PeakRobustEffect_Norm, ...
                AreaRobustEffect_Norm, ...
                SuggestedMeasure, ...
                DiscoveryTier, ...
                DiscoveryScore);
            
            
            if ~isempty(ROITable)
                
                ROITable = ...
                    sortrows( ...
                    ROITable, ...
                    { ...
                    'ROIDomain', ...
                    'DiscoveryScore'}, ...
                    { ...
                    'ascend', ...
                    'descend'});
                
            end
            
            
            % ================================================================
            % FEATURE VALUES
            % ================================================================
            
            FeatureValues = ...
                table();
            
            
            FeatureValues.SpectrumName = ...
                spectrumNames;
            
            
            FeatureValues.AnalysisGroup = ...
                gid;
            
            
            % Need original discovery-order ID to map feature matrices.
            
            originalIDs = ...
                [ ...
                strings(nFP,1); ...
                strings(nHW,1) ...
                ];
            
            
            for k = 1:nFP
                originalIDs(k) = sprintf('FP%02d',k);
            end
            
            
            for k = 1:nHW
                originalIDs(nFP+k) = sprintf('HW%02d',k);
            end
            
            
            for r = 1:numel(originalIDs)
                
                safe = ...
                    matlab.lang.makeValidName( ...
                    originalIDs(r));
                
                
                FeatureValues.( ...
                    ['PeakNorm_' char(safe)]) = ...
                    PeakValuesNorm(:,r);
                
                
                FeatureValues.( ...
                    ['AreaNorm_' char(safe)]) = ...
                    AreaValuesNorm(:,r);
                
                
                FeatureValues.( ...
                    ['PeakUnnorm_' char(safe)]) = ...
                    PeakValuesUnnorm(:,r);
                
                
                FeatureValues.( ...
                    ['AreaUnnorm_' char(safe)]) = ...
                    AreaValuesUnnorm(:,r);
                
            end
            
            
            % ================================================================
            % PREPCA
            % ================================================================
            
            PrePCA = ...
                struct();
            
            
            PrePCA.GroupA = ...
                gA;
            
            
            PrePCA.GroupB = ...
                gB;
            
            
            PrePCA.SpectrumNames = ...
                spectrumNames;
            
            
            PrePCA.GroupID = ...
                gid;
            
            
            PrePCA.Wavenumber = ...
                wn;
            
            
            PrePCA.ProcessedUnnormalized = ...
                X_unorm;
            
            
            PrePCA.ProcessedVectorNormalized = ...
                X_norm;
            
            
            PrePCA.VectorNormFactors = ...
                vectorNorms;
            
            
            PrePCA.MeanA = ...
                meanA;
            
            
            PrePCA.MeanB = ...
                meanB;
            
            
            PrePCA.MedianA = ...
                medianA;
            
            
            PrePCA.MedianB = ...
                medianB;
            
            
            PrePCA.P10A = ...
                p10A;
            
            
            PrePCA.P25A = ...
                p25A;
            
            
            PrePCA.P75A = ...
                p75A;
            
            
            PrePCA.P90A = ...
                p90A;
            
            
            PrePCA.P10B = ...
                p10B;
            
            
            PrePCA.P25B = ...
                p25B;
            
            
            PrePCA.P75B = ...
                p75B;
            
            
            PrePCA.P90B = ...
                p90B;
            
            
            PrePCA.DifferenceMean = ...
                differenceMean;
            
            
            PrePCA.DifferenceMedian = ...
                differenceMedian;
            
            
            PrePCA.EffectStandard = ...
                effectStandard;
            
            
            PrePCA.EffectRobust = ...
                effectRobust;
            
            
            PrePCA.Unnormalized = ...
                struct();
            
            
            PrePCA.Unnormalized.DifferenceMedian = ...
                differenceMedianUn;
            
            
            PrePCA.Unnormalized.EffectRobust = ...
                effectRobustUn;
            
            
            PrePCA.ROITable = ...
                ROITable;
            
            
            PrePCA.FeatureValues = ...
                FeatureValues;
            
            
            PrePCA.Settings = ...
                app.Settings.Discovery;
            
            
            PrePCA.PreparedAt = ...
                string( ...
                datetime( ...
                'now', ...
                'Format','yyyy-MM-dd HH:mm:ss'));
            
            
            % ================================================================
            % STORE
            % ================================================================
            
            app.wn = ...
                wn;
            
            
            app.X_unorm = ...
                X_unorm;
            
            
            app.X_norm = ...
                X_norm;
            
            
            app.VectorNormFactors = ...
                vectorNorms;
            
            
            app.ROITable = ...
                ROITable;
            
            
            app.FeatureValues = ...
                FeatureValues;
            
            
            app.PrePCA = ...
                PrePCA;
            
        end


        function R = discoverROIForDomain(app,wn,domainMask,differenceCurve,effectCurve)
            
            domainDifference = abs(differenceCurve(domainMask));
            domainDifference = domainDifference(isfinite(domainDifference));
            
            if isempty(domainDifference)
                R = struct( ...
                    'DifferenceThreshold',NaN, ...
                    'TierBThreshold',NaN, ...
                    'TierAThreshold',NaN, ...
                    'SelectedPoints',0, ...
                    'Regions',zeros(0,2));
                return
            end
            
            thresholdC = prctile( ...
                domainDifference, ...
                app.Settings.Discovery.DifferencePercentile);
            
            thresholdB = prctile( ...
                domainDifference, ...
                app.Settings.Discovery.TierB_DifferencePercentile);
            
            thresholdA = prctile( ...
                domainDifference, ...
                app.Settings.Discovery.TierA_DifferencePercentile);
            
            % Broad candidate screening
            selected = ...
                domainMask & ...
                abs(differenceCurve) >= thresholdC & ...
                abs(effectCurve) >= ...
                app.Settings.Discovery.RobustEffectThreshold;
            
            selected = app.bridgeSmallSpectralGaps( ...
                selected,wn, ...
                app.Settings.Discovery.MaxGapToBridge_cm1);
            
            regions = app.maskToSpectralRegions(selected,wn);
            
            if ~isempty(regions)
                widths = regions(:,2)-regions(:,1);
                regions = regions( ...
                    widths >= app.Settings.Discovery.MinimumROIWidth_cm1,:);
            end
            
            R = struct();
            R.DifferenceThreshold = thresholdC;
            R.TierBThreshold = thresholdB;
            R.TierAThreshold = thresholdA;
            R.SelectedPoints = nnz(selected);
            R.Regions = regions;
        end


        function maskOut = bridgeSmallSpectralGaps( ...
                app,maskIn,wn,maxGap)
            
            %#ok<INUSD>
            
            
            maskOut = ...
                logical(maskIn(:));
            
            
            trueIdx = ...
                find(maskOut);
            
            
            if numel(trueIdx) < 2
                
                return
                
            end
            
            
            for k = 1:numel(trueIdx)-1
                
                i1 = ...
                    trueIdx(k);
                
                
                i2 = ...
                    trueIdx(k+1);
                
                
                if i2 <= i1+1
                    
                    continue
                    
                end
                
                
                gap = ...
                    abs( ...
                    wn(i2) - ...
                    wn(i1));
                
                
                if gap <= maxGap
                    
                    maskOut(i1:i2) = ...
                        true;
                    
                end
                
            end
            
        end


        function regions = maskToSpectralRegions(app,mask,wn)
            
            %#ok<INUSD>
            
            
            mask = ...
                logical(mask(:));
            
            
            transitions = ...
                diff( ...
                [false;mask;false]);
            
            
            starts = ...
                find( ...
                transitions == 1);
            
            
            stops = ...
                find( ...
                transitions == -1)-1;
            
            
            regions = ...
                [ ...
                wn(starts), ...
                wn(stops) ...
                ];
            
        end


        function runAllPCA(app)
            
            app.PCA_Full = ...
                app.runPCADomain( ...
                app.Settings.Range.Analysis, ...
                "Full 400-3100");
            
            
            app.PCA_Fingerprint = ...
                app.runPCADomain( ...
                app.Settings.Range.Fingerprint, ...
                "Fingerprint 400-1800");
            
            
            app.PCA_HighWN = ...
                app.runPCADomain( ...
                app.Settings.Range.HighWN, ...
                "High-WN 2800-3100");
            
        end
        
        
        function P = runPCADomain(app,range,name)
            
            mask = ...
                app.wn >= range(1) & ...
                app.wn <= range(2);
            
            
            if nnz(mask) < 3
                
                error( ...
                    'PCA domain %s has fewer than 3 variables.', ...
                    name);
                
            end
            
            
            Xobs = ...
                app.X_norm(mask,:)';
            
            
            [coeff,score,latent,~,explained,mu] = ...
                pca( ...
                Xobs, ...
                'Centered',true);
            
            
            P = ...
                struct();
            
            
            P.Name = ...
                string(name);
            
            
            P.Range = ...
                range;
            
            
            P.Wavenumber = ...
                app.wn(mask);
            
            
            P.Coeff = ...
                coeff;
            
            
            P.Loadings = ...
                coeff;
            
            
            P.Scores = ...
                score;
            
            
            P.Latent = ...
                latent;
            
            
            P.Explained = ...
                explained;
            
            
            P.Mean = ...
                mu;
            
            
            P.GroupID = ...
                app.analysisGroupID;
            
            
            P.NumSpectra = ...
                size(Xobs,1);
            
            
            P.NumVariables = ...
                size(Xobs,2);
            
            
            P.NumPC = ...
                size(score,2);
            
        end
        
        
        function populateCandidateTable(app)
            if isempty(app.ROITable)
                app.populateEmptyCandidateTable();
                return
            end
            
            R = app.ROITable;
            n = height(R);
            
            Candidate = string(R.ROIID);
            FeatureType = repmat("INTENSITY",n,1);
            Mode = repmat("SHARED",n,1);
            Domain = string(R.ROIDomain);
            ROILow = R.RegionLow;
            ROIHigh = R.RegionHigh;
            RepresentativeWN = R.RepresentativeWN;
            Direction = string(R.Direction);
            DiscoveryScore = R.DiscoveryScore;
            Validation = repmat("NOT VALIDATED",n,1);
            Decision = repmat("PENDING",n,1);
            
            T = table( ...
                Candidate,FeatureType,Mode,Domain, ...
                ROILow,ROIHigh,RepresentativeWN,Direction, ...
                DiscoveryScore,Validation,Decision);
            
            app.UITable.Data = T;
            
            app.UITable.ColumnName = { ...
                'Candidate', ...
                'Feature type', ...
                'Mode', ...
                'Domain', ...
                'ROI low', ...
                'ROI high', ...
                'Rep. WN', ...
                'Direction', ...
                'Discovery score', ...
                'Validation', ...
                'Decision'};
            
            app.UITable.ColumnEditable = ...
                false(1,11);
            
            app.UITable.ColumnFormat = { ...
                'char','char','char','char', ...
                'numeric','numeric','numeric','char', ...
                'numeric','char','char'};
        end
        
        
        function populateEmptyCandidateTable(app)
            
            app.UITable.Data = ...
                table();
            
        end
        
        

        function showDiscoveryFigures(app)
            
            if isempty(app.PrePCA)
                
                return
                
            end
            
            
            S = ...
                app.PrePCA;
            
            
            gA = ...
                app.groupA;
            
            
            gB = ...
                app.groupB;
            
            
            fp = ...
                app.wn >= ...
                app.Settings.Range.Fingerprint(1) & ...
                app.wn <= ...
                app.Settings.Range.Fingerprint(2);
            
            
            hw = ...
                app.wn >= ...
                app.Settings.Range.HighWN(1) & ...
                app.wn <= ...
                app.Settings.Range.HighWN(2);
            
            
            % ================================================================
            % FIG 1 MEAN
            % ================================================================
            
            f1 = ...
                figure( ...
                'Name','Discovery 01 | Mean spectra', ...
                'Color','w');
            
            
            t = ...
                tiledlayout(1,2);
            
            
            ax = ...
                nexttile(t);
            
            
            plot( ...
                ax, ...
                app.wn(fp), ...
                S.MeanA(fp), ...
                'LineWidth',1.4);
            
            hold(ax,'on');
            
            plot( ...
                ax, ...
                app.wn(fp), ...
                S.MeanB(fp), ...
                'LineWidth',1.4);
            
            hold(ax,'off');
            
            title(ax,'Fingerprint mean');
            
            xlabel(ax,'Raman shift (cm^{-1})');
            
            ylabel(ax,'Vector-normalized intensity');
            
            legend(ax,{char(gA),char(gB)}, ...
                'Interpreter','none');
            
            box(ax,'on');
            
            
            ax = ...
                nexttile(t);
            
            
            plot( ...
                ax, ...
                app.wn(hw), ...
                S.MeanA(hw), ...
                'LineWidth',1.4);
            
            hold(ax,'on');
            
            plot( ...
                ax, ...
                app.wn(hw), ...
                S.MeanB(hw), ...
                'LineWidth',1.4);
            
            hold(ax,'off');
            
            title(ax,'High-WN mean');
            
            xlabel(ax,'Raman shift (cm^{-1})');
            
            ylabel(ax,'Vector-normalized intensity');
            
            legend(ax,{char(gA),char(gB)}, ...
                'Interpreter','none');
            
            box(ax,'on');
            
            
            % ================================================================
            % FIG 2 MEDIAN DIFFERENCE
            % ================================================================
            
            f2 = ...
                figure( ...
                'Name','Discovery 02 | Median difference', ...
                'Color','w');
            
            
            plot( ...
                app.wn, ...
                S.DifferenceMedian, ...
                'LineWidth',1.2);
            
            
            yline(0,'--');
            
            
            xlabel( ...
                'Raman shift (cm^{-1})');
            
            
            ylabel( ...
                sprintf( ...
                'Median difference | %s - %s', ...
                gA,gB));
            
            
            title( ...
                'Whole-spectrum median difference', ...
                'Interpreter','none');
            
            
            box on;
            
            
            % ================================================================
            % FIG 3 ROBUST EFFECT + ROI
            % ================================================================
            
            f3 = ...
                figure( ...
                'Name','Discovery 03 | Robust effect + candidates', ...
                'Color','w');
            
            
            plot( ...
                app.wn, ...
                S.EffectRobust, ...
                'LineWidth',1.2);
            
            
            hold on;
            
            
            yline( ...
                app.Settings.Discovery.RobustEffectThreshold, ...
                '--');
            
            
            yline( ...
                -app.Settings.Discovery.RobustEffectThreshold, ...
                '--');
            
            
            for r = 1:height(app.ROITable)
                
                xline( ...
                    app.ROITable.RepresentativeWN(r), ...
                    ':');
                
            end
            
            
            hold off;
            
            
            xlabel( ...
                'Raman shift (cm^{-1})');
            
            
            ylabel( ...
                'Robust effect');
            
            
            title( ...
                'Robust effect + discovered ROI representatives');
            
            
            box on;
            
            
            % ================================================================
            % SAVE DISCOVERY EVIDENCE
            % ================================================================
            
            if app.OutputRoot ~= ""
                
                root = ...
                    fullfile( ...
                    app.OutputRoot, ...
                    app.Settings.Output.DiscoveryFolder);
                
                
                if ~isfolder(root)
                    
                    mkdir(root);
                    
                end
                
                
                app.safeExportFigure( ...
                    f1, ...
                    fullfile(root,'Fig01_MeanSpectra.png'));
                
                
                app.safeExportFigure( ...
                    f2, ...
                    fullfile(root,'Fig02_MedianDifference.png'));
                
                
                app.safeExportFigure( ...
                    f3, ...
                    fullfile(root,'Fig03_RobustEffectCandidates.png'));
                
            end
            
        end


        function showDiscoverySummary(app)
            
            nFP = ...
                nnz( ...
                string(app.ROITable.ROIDomain) == ...
                "Fingerprint");
            
            
            nHW = ...
                nnz( ...
                string(app.ROITable.ROIDomain) == ...
                "High-WN");
            
            
            lines = ...
                { ...
                'CANDIDATE DISCOVERY COMPLETE'; ...
                '────────────────────────────────'; ...
                ''; ...
                sprintf( ...
                'Total spectra: %d', ...
                size(app.X_norm,2)); ...
                sprintf( ...
                'WN points: %d', ...
                numel(app.wn)); ...
                ''; ...
                'NORMALIZATION'; ...
                '────────────────────────────────'; ...
                'Spectrum-wise L2 vector normalization'; ...
                sprintf( ...
                'Range: %.0f-%.0f cm^-1', ...
                app.Settings.Range.Analysis); ...
                ''; ...
                'DISCOVERY'; ...
                '────────────────────────────────'; ...
                sprintf( ...
                'Fingerprint candidates: %d', ...
                nFP); ...
                sprintf( ...
                'High-WN candidates: %d', ...
                nHW); ...
                sprintf( ...
                'Total candidates: %d', ...
                height(app.ROITable)); ...
                ''; ...
                sprintf( ...
                'Difference percentile: %.1f', ...
                app.Settings.Discovery.DifferencePercentile); ...
                sprintf( ...
                'Robust effect threshold: %.2f', ...
                app.Settings.Discovery.RobustEffectThreshold); ...
                ''; ...
                'PCA'; ...
                '────────────────────────────────'; ...
                sprintf( ...
                'Full PC1/2/3: %.2f / %.2f / %.2f %%', ...
                app.firstExplained(app.PCA_Full,1), ...
                app.firstExplained(app.PCA_Full,2), ...
                app.firstExplained(app.PCA_Full,3)); ...
                sprintf( ...
                'Fingerprint PC1/2/3: %.2f / %.2f / %.2f %%', ...
                app.firstExplained(app.PCA_Fingerprint,1), ...
                app.firstExplained(app.PCA_Fingerprint,2), ...
                app.firstExplained(app.PCA_Fingerprint,3)); ...
                sprintf( ...
                'High-WN PC1/2/3: %.2f / %.2f / %.2f %%', ...
                app.firstExplained(app.PCA_HighWN,1), ...
                app.firstExplained(app.PCA_HighWN,2), ...
                app.firstExplained(app.PCA_HighWN,3)); ...
                ''; ...
                'Select any candidate row to inspect it.'; ...
                'Validation mode is determined automatically by Feature type:'; ...
                'INTENSITY -> SHARED | POSITION -> SHIFTED | FWHM -> WIDTH.' ...
                };
            
            
            app.setTextArea(lines);
            
        end
        
        
        function showSelectedCandidateSummary(app)
            if ~app.requireSelectedCandidate()
                return
            end
            
            T = app.UITable.Data;
            r = app.SelectedCandidateRow;
            
            lines = { ...
                'SELECTED CANDIDATE'; ...
                '────────────────────────────────'; ...
                ''; ...
                sprintf('Candidate: %s',T.Candidate(r)); ...
                sprintf('Feature type: %s',T.FeatureType(r)); ...
                sprintf('Validation mode: %s',T.Mode(r)); ...
                sprintf('Domain: %s',T.Domain(r)); ...
                sprintf('ROI/search: %.2f - %.2f cm^-1', ...
                T.ROILow(r),T.ROIHigh(r)); ...
                sprintf('Representative WN: %.2f cm^-1', ...
                T.RepresentativeWN(r)); ...
                sprintf('Trend: %s',T.Direction(r)); ...
                ''; ...
                sprintf('Validation: %s',T.Validation(r)); ...
                sprintf('Decision: %s',T.Decision(r)) ...
                };
            
            isCustom = ...
                startsWith( ...
                string(T.Candidate(r)), ...
                "CUS_");
            
            if isCustom
                
                lines{end+1} = ...
                    'Candidate source: USER CUSTOM';
                
                lines{end+1} = ...
                    'This feature was manually proposed for formal validation.';
                
            elseif T.FeatureType(r) == "INTENSITY"
                
                lines{end+1} = sprintf( ...
                    'Discovery score: %.4f', ...
                    T.DiscoveryScore(r));
                
                lines{end+1} = ...
                    'Intensity candidate was detected automatically.';
                
            else
                
                lines{end+1} = ...
                    'Morphology candidate was selected by human screening.';
            end
            
            app.setTextArea(lines);
        end
        
        
        function plotSharedValidationFourAxes(app,R)
            
            % ================================================================
            % SHARED VALIDATION VIEW
            %
            % AXIS 1 = local unnormalized spectra
            % AXIS 2 = local normalized spectra
            % AXIS 3 = normalized feature distribution
            % AXIS 4 = PCA loading
            %
            % IMPORTANT:
            % cla(...,'reset') is REQUIRED because different candidate modes
            % leave different XLim / XTick / hidden graphics on the same UIAxes.
            % ================================================================
            
            displayHalfWindow = app.Settings.Shared.DisplayHalfWindow_cm1;
            
            displayMask = ...
                app.wn >= R.WN-displayHalfWindow & ...
                app.wn <= R.WN+displayHalfWindow;
            
            if nnz(displayMask) < 2
                error('Display spectral window contains too few points.');
            end
            
            displayWN = app.wn(displayMask);
            
            gid = app.analysisGroupID;
            idxA = gid == app.groupA;
            idxB = gid == app.groupB;
            
            specA_un = app.X_unorm(displayMask,idxA);
            specB_un = app.X_unorm(displayMask,idxB);
            
            specA_n = app.X_norm(displayMask,idxA);
            specB_n = app.X_norm(displayMask,idxB);
            
            groupColors = lines(2);
            colorA = groupColors(1,:);
            colorB = groupColors(2,:);
            
            % ================================================================
            % AXIS 1 - LOCAL UNNORMALIZED SPECTRA
            % ================================================================
            
            ax = app.UIAxes;
            
            % CRITICAL:
            % reset removes old hidden lines AND old xlim/xticks
            cla(ax,'reset');
            hold(ax,'on');
            
            for k = 1:size(specA_un,2)
                plot(ax,displayWN,specA_un(:,k), ...
                    'Color',colorA, ...
                    'LineWidth',0.35, ...
                    'HandleVisibility','off');
            end
            
            for k = 1:size(specB_un,2)
                plot(ax,displayWN,specB_un(:,k), ...
                    'Color',colorB, ...
                    'LineWidth',0.35, ...
                    'HandleVisibility','off');
            end
            
            % Robust group centre
            hA = plot(ax,displayWN, ...
                median(specA_un,2,'omitnan'), ...
                'Color',colorA, ...
                'LineWidth',2.2, ...
                'DisplayName',char(app.groupA));
            
            hB = plot(ax,displayWN, ...
                median(specB_un,2,'omitnan'), ...
                'Color',colorB, ...
                'LineWidth',2.2, ...
                'DisplayName',char(app.groupB));
            
            xline(ax,R.ROILow,':', ...
                'HandleVisibility','off');
            
            xline(ax,R.ROIHigh,':', ...
                'HandleVisibility','off');
            
            xline(ax,R.WN,'--', ...
                'HandleVisibility','off');
            
            hold(ax,'off');
            
            % FORCE CORRECT LOCAL WINDOW
            xlim(ax,[min(displayWN) max(displayWN)]);
            ax.XTickMode = 'auto';
            ax.XTickLabelMode = 'auto';
            
            xlabel(ax,'Raman shift (cm^{-1})');
            ylabel(ax,'Unnormalized intensity');
            
            title(ax, ...
                sprintf( ...
                'Local spectra | %s | %.1f cm^{-1} | unnormalized', ...
                R.CandidateID,R.WN), ...
                'Interpreter','none');
            
            legend(ax,[hA hB], ...
                {char(app.groupA),char(app.groupB)}, ...
                'Interpreter','none', ...
                'Location','best');
            
            box(ax,'on');
            grid(ax,'off');
            
            % ================================================================
            % AXIS 2 - LOCAL VECTOR-NORMALIZED SPECTRA
            % ================================================================
            
            ax = app.UIAxes_2;
            
            % CRITICAL reset
            cla(ax,'reset');
            hold(ax,'on');
            
            for k = 1:size(specA_n,2)
                plot(ax,displayWN,specA_n(:,k), ...
                    'Color',colorA, ...
                    'LineWidth',0.35, ...
                    'HandleVisibility','off');
            end
            
            for k = 1:size(specB_n,2)
                plot(ax,displayWN,specB_n(:,k), ...
                    'Color',colorB, ...
                    'LineWidth',0.35, ...
                    'HandleVisibility','off');
            end
            
            hA = plot(ax,displayWN, ...
                median(specA_n,2,'omitnan'), ...
                'Color',colorA, ...
                'LineWidth',2.2, ...
                'DisplayName',char(app.groupA));
            
            hB = plot(ax,displayWN, ...
                median(specB_n,2,'omitnan'), ...
                'Color',colorB, ...
                'LineWidth',2.2, ...
                'DisplayName',char(app.groupB));
            
            xline(ax,R.ROILow,':', ...
                'HandleVisibility','off');
            
            xline(ax,R.ROIHigh,':', ...
                'HandleVisibility','off');
            
            xline(ax,R.WN,'--', ...
                'HandleVisibility','off');
            
            hold(ax,'off');
            
            % FORCE CORRECT RAMAN AXIS
            xlim(ax,[min(displayWN) max(displayWN)]);
            ax.XTickMode = 'auto';
            ax.XTickLabelMode = 'auto';
            
            xlabel(ax,'Raman shift (cm^{-1})');
            ylabel(ax,'Vector-normalized intensity');
            
            title(ax, ...
                sprintf( ...
                'Local spectra | %s | %.1f cm^{-1} | normalized', ...
                R.CandidateID,R.WN), ...
                'Interpreter','none');
            
            legend(ax,[hA hB], ...
                {char(app.groupA),char(app.groupB)}, ...
                'Interpreter','none', ...
                'Location','best');
            
            box(ax,'on');
            grid(ax,'off');
            
            % ================================================================
            % AXIS 3 - PRIMARY FEATURE DISTRIBUTION
            % ================================================================
            
            app.plotTwoGroupBox( ...
                app.UIAxes_3, ...
                R.FeatureNormA, ...
                R.FeatureNormB, ...
                app.groupA, ...
                app.groupB);
            
            ylabel( ...
                app.UIAxes_3, ...
                R.PrimaryMeasurement + " normalized feature");
            
            title( ...
                app.UIAxes_3, ...
                sprintf( ...
                '%s | E=%.3f | p=%.3g | boot=%.1f%%', ...
                R.PrimaryMeasurement, ...
                R.EffectNorm, ...
                R.P_Norm, ...
                100*R.Bootstrap), ...
                'Interpreter','none');
            
            % ================================================================
            % AXIS 4 - PCA LOADING
            % ================================================================
            
            P = R.PCAInfo.Model;
            pc = R.PCAInfo.BestPC;
            
            ax = app.UIAxes_4;
            
            cla(ax,'reset');
            
            plot(ax,P.Wavenumber,P.Loadings(:,pc), ...
                'LineWidth',1.3);
            
            hold(ax,'on');
            
            yline(ax,0,'--', ...
                'HandleVisibility','off');
            
            xline(ax,R.ROILow,':', ...
                'HandleVisibility','off');
            
            xline(ax,R.ROIHigh,':', ...
                'HandleVisibility','off');
            
            xline(ax,R.WN,'--', ...
                'HandleVisibility','off');
            
            if isfinite(R.LoadingExtremumWN)
                xline(ax,R.LoadingExtremumWN,'-.', ...
                    'HandleVisibility','off');
            end
            
            hold(ax,'off');
            
            loadingLow = ...
                max(min(P.Wavenumber), ...
                R.WN-displayHalfWindow);
            
            loadingHigh = ...
                min(max(P.Wavenumber), ...
                R.WN+displayHalfWindow);
            
            xlim(ax,[loadingLow loadingHigh]);
            
            ax.XTickMode = 'auto';
            ax.XTickLabelMode = 'auto';
            
            xlabel(ax,'Raman shift (cm^{-1})');
            ylabel(ax,sprintf('PC%d loading',pc));
            
            title(ax, ...
                sprintf( ...
                'PCA loading | PASS=%d | max|L|=%.4g | threshold=%.4g', ...
                R.PCALoadingSupport, ...
                R.MaxAbsLoading, ...
                R.LoadingThreshold), ...
                'Interpreter','none');
            
            box(ax,'on');
            grid(ax,'off');
            
        end
        
        
        function R = validateSharedCandidate(app,measurementChoice,doPlot)
            
            if nargin < 2 || measurementChoice == ""
                measurementChoice = "AUTO";
            end
            
            if nargin < 3
                doPlot = true;
            end
            
            row = ...
                app.SelectedCandidateRow;
            
            
            T = ...
                app.UITable.Data;
            
            
            candidateID = ...
                string(T.Candidate(row));
            
            
            targetWN = ...
                T.RepresentativeWN(row);
            
            
            roiLow = ...
                T.ROILow(row);
            
            
            roiHigh = ...
                T.ROIHigh(row);
            
            
            % ================================================================
            % PRIMARY MEASUREMENT
            %
            % AUTO is used by batch validation.
            % ================================================================
            
            measurementChoice = upper(string(measurementChoice));
            
            if measurementChoice == "AUTO"
                
                idxROI = find( ...
                    string(app.ROITable.ROIID) == candidateID, ...
                    1);
                
                if isempty(idxROI)
                    error('Discovery ROI metadata not found for %s.',candidateID);
                end
                
                suggestion = ...
                    string(app.ROITable.SuggestedMeasure(idxROI));
                
                if contains(lower(suggestion),"area preferred")
                    measurementChoice = "AREA";
                else
                    measurementChoice = "PEAK";
                end
            end
            
            if measurementChoice ~= "PEAK" && ...
                    measurementChoice ~= "AREA"
                
                error( ...
                    'Unsupported SHARED measurement: %s', ...
                    measurementChoice);
            end
            
            if measurementChoice == "PEAK"
                measurementChoice = "Peak";
            else
                measurementChoice = "Area";
            end
            
            
            % ================================================================
            % MAP TO ACTUAL GRID
            % ================================================================
            
            [~,idxRep] = ...
                min( ...
                abs(app.wn-targetWN));
            
            
            actualWN = ...
                app.wn(idxRep);
            
            
            roiMask = ...
                app.wn >= roiLow & ...
                app.wn <= roiHigh;
            
            
            if nnz(roiMask) < 2
                
                error( ...
                    'Candidate ROI %.2f-%.2f contains fewer than 2 WN points.', ...
                    roiLow, ...
                    roiHigh);
                
            end
            
            
            roiWN = ...
                app.wn(roiMask);
            
            
            actualROILow = ...
                min(roiWN);
            
            
            actualROIHigh = ...
                max(roiWN);
            
            
            % ================================================================
            % GROUPS
            % ================================================================
            
            gid = ...
                app.analysisGroupID;
            
            
            idxA = ...
                gid == app.groupA;
            
            
            idxB = ...
                gid == app.groupB;
            
            
            % ================================================================
            % BUILD PEAK + AREA
            % ================================================================
            
            PeakNorm = ...
                app.X_norm(idxRep,:)';
            
            
            PeakUnnorm = ...
                app.X_unorm(idxRep,:)';
            
            
            AreaNorm = ...
                trapz( ...
                roiWN, ...
                app.X_norm(roiMask,:), ...
                1)';
            
            
            AreaUnnorm = ...
                trapz( ...
                roiWN, ...
                app.X_unorm(roiMask,:), ...
                1)';
            
            
            if measurementChoice == "Peak"
                
                NumNorm = ...
                    PeakNorm;
                
                
                NumUnnorm = ...
                    PeakUnnorm;
                
            else
                
                NumNorm = ...
                    AreaNorm;
                
                
                NumUnnorm = ...
                    AreaUnnorm;
                
            end
            
            
            NumNormA = ...
                NumNorm(idxA);
            
            
            NumNormB = ...
                NumNorm(idxB);
            
            
            NumUnnormA = ...
                NumUnnorm(idxA);
            
            
            NumUnnormB = ...
                NumUnnorm(idxB);
            
            
            % ================================================================
            % PRIMARY STATISTICS
            % ================================================================
            
            PrimaryEffectNorm = ...
                app.robustEffectLocal( ...
                NumNormA, ...
                NumNormB);
            
            
            PrimaryEffectUnnorm = ...
                app.robustEffectLocal( ...
                NumUnnormA, ...
                NumUnnormB);
            
            
            PrimaryIQRrelA = ...
                app.relativeIQRlocal( ...
                NumNormA);
            
            
            PrimaryIQRrelB = ...
                app.relativeIQRlocal( ...
                NumNormB);
            
            
            % ================================================================
            % NORMALIZED / UNNORMALIZED DIRECTION CONSISTENCY
            %
            % Zero-zero does NOT count as directional agreement.
            % ================================================================
            
            signNorm = ...
                sign(PrimaryEffectNorm);
            
            signUnnorm = ...
                sign(PrimaryEffectUnnorm);
            
            NormalizationDirectionAgreement = ...
                isfinite(PrimaryEffectNorm) && ...
                isfinite(PrimaryEffectUnnorm) && ...
                signNorm == signUnnorm && ...
                signNorm ~= 0;
            
            
            pPrimaryNorm = ...
                app.safeRanksum( ...
                NumNormA, ...
                NumNormB);
            
            
            pPrimaryUnnorm = ...
                app.safeRanksum( ...
                NumUnnormA, ...
                NumUnnormB);
            
            
            seed = ...
                app.seedFromText( ...
                app.Settings.RandomSeed, ...
                "SHARED_"+candidateID);
            
            
            oldRng = ...
                rng;
            
            
            cleanup = ...
                onCleanup( ...
                @()rng(oldRng)); %#ok<NASGU>
            
            
            rng(seed,'twister');
            
            
            PrimaryBootstrapStability = ...
                app.bootstrapDirectionStability( ...
                NumNormA, ...
                NumNormB, ...
                app.Settings.Shared.NBootstrap);
            
            
            % ================================================================
            % PCA
            % ================================================================
            
            PInfo = ...
                app.evaluateSharedPCARegion( ...
                actualWN, ...
                actualROILow, ...
                actualROIHigh);
            
            
            % ================================================================
            % EXACT FIVE CORE CRITERIA
            % ================================================================
            
            EffectSupport = ...
                isfinite(PrimaryEffectNorm) && ...
                abs(PrimaryEffectNorm) >= ...
                app.Settings.Shared.MinEffectNorm;
            
            
            DistributionSupport = ...
                isfinite(PrimaryIQRrelA) && ...
                isfinite(PrimaryIQRrelB) && ...
                PrimaryIQRrelA <= ...
                app.Settings.Shared.MaxRelativeIQR && ...
                PrimaryIQRrelB <= ...
                app.Settings.Shared.MaxRelativeIQR;
            
            
            BootstrapSupport = ...
                isfinite(PrimaryBootstrapStability) && ...
                PrimaryBootstrapStability >= ...
                app.Settings.Shared.MinBootstrapDirectionStability;
            
            
            NormalizationSupport = ...
                logical( ...
                NormalizationDirectionAgreement);
            
            
            PCASupport = ...
                logical( ...
                PInfo.Support);
            
            
            CoreEvidenceCount = ...
                double(EffectSupport) ...
                + double(DistributionSupport) ...
                + double(BootstrapSupport) ...
                + double(NormalizationSupport) ...
                + double(PCASupport);
            
            
            if CoreEvidenceCount == 5
                
                CandidateStatus = ...
                    "STRONG SPECTRAL EVIDENCE";
                
                
            elseif CoreEvidenceCount >= 3
                
                CandidateStatus = ...
                    "PROMISING SPECTRAL EVIDENCE";
                
                
            else
                
                CandidateStatus = ...
                    "WEAK / REQUIRES REVIEW";
                
            end
            
            
            % ================================================================
            % DIRECTION
            % ================================================================
            
            if PrimaryEffectNorm > 0
                
                direction = ...
                    app.groupA + ...
                    " > " + ...
                    app.groupB;
                
                
            elseif PrimaryEffectNorm < 0
                
                direction = ...
                    app.groupB + ...
                    " > " + ...
                    app.groupA;
                
                
            else
                
                direction = ...
                    "No median direction";
                
            end
            
            
            % ================================================================
            % RESULT
            % ================================================================
            
            R = struct();
            
            
            R.CandidateID = ...
                candidateID;
            
            
            R.Mode = ...
                "SHARED";
            
            
            R.ValidationTime = ...
                app.nowString();
            
            
            R.GroupA = ...
                app.groupA;
            
            
            R.GroupB = ...
                app.groupB;
            
            
            R.WN = ...
                actualWN;
            
            
            R.ROILow = ...
                actualROILow;
            
            
            R.ROIHigh = ...
                actualROIHigh;
            
            
            R.PrimaryMeasurement = ...
                measurementChoice;
            
            
            R.Direction = ...
                direction;
            
            
            R.EffectNorm = ...
                PrimaryEffectNorm;
            
            
            R.EffectUnnorm = ...
                PrimaryEffectUnnorm;
            
            
            R.RelativeIQR_A = ...
                PrimaryIQRrelA;
            
            
            R.RelativeIQR_B = ...
                PrimaryIQRrelB;
            
            
            R.P_Norm = ...
                pPrimaryNorm;
            
            
            R.P_Unnorm = ...
                pPrimaryUnnorm;
            
            
            R.Bootstrap = ...
                PrimaryBootstrapStability;
            
            
            R.DirectionAgreement = ...
                NormalizationDirectionAgreement;
            
            
            R.PCADomain = ...
                PInfo.Domain;
            
            
            R.BestPC = ...
                PInfo.BestPC;
            
            
            R.SecondPC = ...
                PInfo.SecondPC;
            
            
            R.PCScoreEffect = ...
                PInfo.ScoreEffect;
            
            
            R.PCALoadingSupport = ...
                PInfo.Support;
            
            
            R.LoadingLevel = ...
                "P" + ...
                string(app.Settings.Shared.PCALoadingPercentile);
            
            
            R.LoadingExtremumWN = ...
                PInfo.LoadingExtremumWN;
            
            
            R.MaxAbsLoading = ...
                PInfo.MaxLoading;
            
            
            R.LoadingThreshold = ...
                PInfo.LoadingThreshold;
            
            
            R.EvidenceEffect = ...
                EffectSupport;
            
            
            R.EvidenceDistribution = ...
                DistributionSupport;
            
            
            R.EvidenceBootstrap = ...
                BootstrapSupport;
            
            
            R.EvidenceNormalization = ...
                NormalizationSupport;
            
            
            R.EvidencePCA = ...
                PCASupport;
            
            
            R.Score = ...
                CoreEvidenceCount;
            
            
            R.Status = ...
                CandidateStatus;
            
            
            R.Decision = ...
                "PENDING";
            
            
            R.FeatureNormA = ...
                NumNormA;
            
            
            R.FeatureNormB = ...
                NumNormB;
            
            
            R.FeatureUnnormA = ...
                NumUnnormA;
            
            
            R.FeatureUnnormB = ...
                NumUnnormB;
            
            
            R.PCAInfo = ...
                PInfo;
            
            
            % ================================================================
            % PLOT 4 AXES
            % ================================================================
            
            if doPlot
                app.plotSharedValidationFourAxes(R);
            end
            
        end


        function plotShiftValidationFourAxes(app,R)
            
            % ================================================================
            % SHIFT VALIDATION VIEW
            %
            % AXIS 1 = local normalized median spectra
            % AXIS 2 = peak-position distributions
            % AXIS 3 = bootstrap shift distribution
            % AXIS 4 = difference spectrum
            % ================================================================
            
            searchMask = ...
                app.wn >= R.SearchLow & ...
                app.wn <= R.SearchHigh;
            
            
            gid = ...
                app.analysisGroupID;
            
            
            idxA = ...
                gid == app.groupA;
            
            
            idxB = ...
                gid == app.groupB;
            
            
            searchWN = ...
                app.wn(searchMask);
            
            
            % ================================================================
            % AXIS 1 - LOCAL MEDIAN SPECTRA
            % ================================================================
            
            medA = ...
                median( ...
                app.X_norm(searchMask,idxA), ...
                2,'omitnan');
            
            medB = ...
                median( ...
                app.X_norm(searchMask,idxB), ...
                2,'omitnan');
            
            groupColors = lines(2);
            
            colorA = groupColors(1,:);
            colorB = groupColors(2,:);
            
            ax = app.UIAxes;
            
            cla(ax,'reset');
            hold(ax,'on');
            
            hA = plot( ...
                ax, ...
                searchWN, ...
                medA, ...
                'Color',colorA, ...
                'LineWidth',1.8, ...
                'DisplayName',char(app.groupA));
            
            hB = plot( ...
                ax, ...
                searchWN, ...
                medB, ...
                'Color',colorB, ...
                'LineWidth',1.8, ...
                'DisplayName',char(app.groupB));
            
            xline( ...
                ax, ...
                R.MedianPeakA, ...
                '--', ...
                'Color',colorA, ...
                'LineWidth',1.2, ...
                'HandleVisibility','off');
            
            xline( ...
                ax, ...
                R.MedianPeakB, ...
                '--', ...
                'Color',colorB, ...
                'LineWidth',1.2, ...
                'HandleVisibility','off');
            
            hold(ax,'off');
            
            xlabel(ax,'Raman shift (cm^{-1})');
            ylabel(ax,'Vector-normalized intensity');
            
            title( ...
                ax, ...
                sprintf( ...
                'Median spectra | %s %.2f | %s %.2f cm^{-1}', ...
                app.groupA, ...
                R.MedianPeakA, ...
                app.groupB, ...
                R.MedianPeakB), ...
                'Interpreter','none');
            
            legend( ...
                ax, ...
                [hA hB], ...
                {char(app.groupA),char(app.groupB)}, ...
                'Interpreter','none', ...
                'Location','best');
            
            box(ax,'on');
            
            
            % ================================================================
            % AXIS 2 - POSITION DISTRIBUTION
            % ================================================================
            
            app.plotTwoGroupBox( ...
                app.UIAxes_2, ...
                R.PositionA, ...
                R.PositionB, ...
                app.groupA, ...
                app.groupB);
            
            
            ylabel( ...
                app.UIAxes_2, ...
                'Refined peak position (cm^{-1})');
            
            
            title( ...
                app.UIAxes_2, ...
                sprintf( ...
                'Shift B-A = %+.3f cm^{-1} | p=%.3g', ...
                R.Shift_B_minus_A, ...
                R.PositionP), ...
                'Interpreter','none');
            
            
            % ================================================================
            % AXIS 3 - BOOTSTRAP DISTRIBUTION
            % ================================================================
            
            ax = ...
                app.UIAxes_3;
            
            
            cla(ax,'reset');
            
            
            histogram( ...
                ax, ...
                R.BootstrapShift);
            
            
            hold(ax,'on');
            
            
            xline( ...
                ax, ...
                0, ...
                '--');
            
            
            xline( ...
                ax, ...
                R.CILow, ...
                ':');
            
            
            xline( ...
                ax, ...
                R.CIHigh, ...
                ':');
            
            
            xline( ...
                ax, ...
                R.Shift_B_minus_A, ...
                '-.');
            
            
            hold(ax,'off');
            
            
            xlabel( ...
                ax, ...
                'Bootstrap shift B-A (cm^{-1})');
            
            
            ylabel( ...
                ax, ...
                'Count');
            
            
            title( ...
                ax, ...
                sprintf( ...
                'Bootstrap %.1f%% | CI %.2f to %.2f', ...
                100*R.Bootstrap, ...
                R.CILow, ...
                R.CIHigh), ...
                'Interpreter','none');
            
            
            box(ax,'on');
            
            
            % ================================================================
            % AXIS 4 - DIFFERENCE SPECTRUM
            % ================================================================
            
            ax = ...
                app.UIAxes_4;
            
            
            cla(ax,'reset');
            
            
            plot( ...
                ax, ...
                R.SearchWN, ...
                R.DifferenceCurve, ...
                'LineWidth',1.4);
            
            
            hold(ax,'on');
            
            
            yline( ...
                ax, ...
                0, ...
                '--');
            
            
            xline( ...
                ax, ...
                R.ExpectedA, ...
                ':');
            
            
            xline( ...
                ax, ...
                R.ExpectedB, ...
                ':');
            
            
            hold(ax,'off');
            
            
            xlabel( ...
                ax, ...
                'Raman shift (cm^{-1})');
            
            
            ylabel( ...
                ax, ...
                'Median normalized difference');
            
            
            title( ...
                ax, ...
                sprintf( ...
                'Difference opposite-sign=%d | PCA=%s | localExt=%d', ...
                R.DifferenceOppositeSign, ...
                R.PCALoadingLevel, ...
                R.PCALocalExtremum), ...
                'Interpreter','none');
            
            
            box(ax,'on');
            
        end


        function R = validateShiftCandidate(app,doPlot)
            
            if nargin < 2
                doPlot = true;
            end
            
            % ================================================================
            % SHIFTED PEAK VALIDATION
            %
            % Preserved from shifted_check.m
            %
            % FIVE CORE EVIDENCE:
            %
            % 1 adequate representation
            % 2 absolute shift >= threshold
            % 3 compact position distributions
            % 4 bootstrap stable + CI excludes zero
            % 5 difference-spectrum OR PCA support
            %
            % pPosition and PositionEffect are REPORTED, not score criteria.
            % ================================================================
            
            row = ...
                app.SelectedCandidateRow;
            
            
            T = ...
                app.UITable.Data;
            
            
            candidateID = ...
                string(T.Candidate(row));
            
            
            defaultCenter = ...
                T.RepresentativeWN(row);
            
            
            defaultLow = ...
                T.ROILow(row) - ...
                10;
            
            
            defaultHigh = ...
                T.ROIHigh(row) + ...
                10;
            
            % ================================================================
            % EXPECTED POSITIONS FROM HUMAN MORPHOLOGY SCREENING
            % ================================================================
            
            idxM = find( ...
                string(app.MorphologyCandidates.Candidate) == candidateID, ...
                1);
            
            if isempty(idxM)
                error( ...
                    'POSITION morphology metadata was not found for %s.', ...
                    candidateID);
            end
            
            expectedA = ...
                app.MorphologyCandidates.ExpectedPositionA(idxM);
            
            expectedB = ...
                app.MorphologyCandidates.ExpectedPositionB(idxM);
            
            searchLow = ...
                app.MorphologyCandidates.SearchLow(idxM);
            
            searchHigh = ...
                app.MorphologyCandidates.SearchHigh(idxM);
            
            if any(~isfinite([expectedA expectedB searchLow searchHigh]))
                error('Invalid morphology-screened peak parameters.');
            end
            
            if searchLow >= searchHigh
                error('Invalid morphology search window.');
            end
            
            % ================================================================
            % MAP SEARCH WINDOW TO ACTUAL SPECTRAL GRID
            % ================================================================
            
            searchMask = ...
                app.wn >= searchLow & ...
                app.wn <= searchHigh;
            
            if nnz(searchMask) < 7
                error( ...
                    'SHIFT search window %.2f-%.2f cm^-1 contains too few points.', ...
                    searchLow,searchHigh);
            end
            
            searchWN = ...
                app.wn(searchMask);
            
            actualSearchLow = ...
                min(searchWN);
            
            actualSearchHigh = ...
                max(searchWN);
            
            
            % ================================================================
            % GROUPS
            % ================================================================
            
            gid = ...
                app.analysisGroupID;
            
            
            idxA = ...
                gid == app.groupA;
            
            
            idxB = ...
                gid == app.groupB;
            
            
            nA = ...
                nnz(idxA);
            
            
            nB = ...
                nnz(idxB);
            
            
            % ================================================================
            % PEAK DETECTION
            % ================================================================
            
            PeakA_un = ...
                app.detectShiftPeakSet( ...
                app.X_unorm(:,idxA), ...
                searchMask, ...
                expectedA);
            
            
            PeakB_un = ...
                app.detectShiftPeakSet( ...
                app.X_unorm(:,idxB), ...
                searchMask, ...
                expectedB);
            
            
            PeakA_n = ...
                app.detectShiftPeakSet( ...
                app.X_norm(:,idxA), ...
                searchMask, ...
                expectedA);
            
            
            PeakB_n = ...
                app.detectShiftPeakSet( ...
                app.X_norm(:,idxB), ...
                searchMask, ...
                expectedB);
            
            
            % ================================================================
            % EVIDENCE 1 - REPRESENTATION
            % ================================================================
            
            detectA = ...
                PeakA_un.Detected;
            
            
            detectB = ...
                PeakB_un.Detected;
            
            
            nDetectA = ...
                nnz(detectA);
            
            
            nDetectB = ...
                nnz(detectB);
            
            
            prevA = ...
                nDetectA ./ ...
                nA;
            
            
            prevB = ...
                nDetectB ./ ...
                nB;
            
            
            AdequateRepresentationPass = ...
                prevA >= ...
                app.Settings.Shift.MinPeakPrevalenceBothGroups && ...
                prevB >= ...
                app.Settings.Shift.MinPeakPrevalenceBothGroups && ...
                nDetectA >= ...
                app.Settings.Shift.MinDetectedPeaksPerGroup && ...
                nDetectB >= ...
                app.Settings.Shift.MinDetectedPeaksPerGroup;
            
            
            % ================================================================
            % NORMALIZED / UNNORMALIZED DETECTION AGREEMENT
            %
            % Reported only.
            % Not a core evidence criterion in shifted_check.
            % ================================================================
            
            DetectionAgreementA = ...
                mean( ...
                PeakA_un.Detected == ...
                PeakA_n.Detected);
            
            
            DetectionAgreementB = ...
                mean( ...
                PeakB_un.Detected == ...
                PeakB_n.Detected);
            
            
            DetectionAgreementOverall = ...
                ( ...
                nA.*DetectionAgreementA + ...
                nB.*DetectionAgreementB) ./ ...
                (nA+nB);
            
            
            % ================================================================
            % POSITIONS
            % ================================================================
            
            posA = ...
                PeakA_un.RefinedPeakWN( ...
                PeakA_un.Detected);
            
            
            posB = ...
                PeakB_un.RefinedPeakWN( ...
                PeakB_un.Detected);
            
            
            posA = ...
                posA(isfinite(posA));
            
            
            posB = ...
                posB(isfinite(posB));
            
            
            if numel(posA) < 3 || ...
                    numel(posB) < 3
                
                error( ...
                    ['Too few detected peaks for peak-shift analysis. ' ...
                    'Need at least 3 valid peak positions in each group.']);
                
            end
            
            
            % ================================================================
            % EVIDENCE 2 - POSITION SHIFT
            % ================================================================
            
            medianA = ...
                median(posA);
            
            
            medianB = ...
                median(posB);
            
            
            Shift_B_minus_A = ...
                medianB - ...
                medianA;
            
            
            AbsShift = ...
                abs( ...
                Shift_B_minus_A);
            
            
            PositionShiftPass = ...
                AbsShift >= ...
                app.Settings.Shift.MinAbsoluteShift_cm1;
            
            
            pPosition = ...
                app.safeRanksum( ...
                posA, ...
                posB);
            
            
            PositionEffect = ...
                app.robustEffectLocal( ...
                posB, ...
                posA);
            
            
            % ================================================================
            % EVIDENCE 3 - COMPACTNESS
            % ================================================================
            
            IQR_A = ...
                iqr(posA);
            
            
            IQR_B = ...
                iqr(posB);
            
            
            MAD_A = ...
                median( ...
                abs(posA-medianA));
            
            
            MAD_B = ...
                median( ...
                abs(posB-medianB));
            
            
            PositionCompactPass = ...
                IQR_A <= ...
                app.Settings.Shift.MaxPositionIQR_cm1 && ...
                IQR_B <= ...
                app.Settings.Shift.MaxPositionIQR_cm1;
            
            
            % ================================================================
            % EVIDENCE 4 - BOOTSTRAP
            % ================================================================
            
            seed = ...
                app.seedFromText( ...
                app.Settings.RandomSeed, ...
                "SHIFT_"+candidateID);
            
            
            oldRng = ...
                rng;
            
            
            cleanup = ...
                onCleanup( ...
                @()rng(oldRng)); %#ok<NASGU>
            
            
            rng(seed,'twister');
            
            
            [bootShift, ...
                BootstrapDirectionStability, ...
                ShiftCILow, ...
                ShiftCIHigh] = ...
                app.bootstrapShiftDifference( ...
                posA, ...
                posB, ...
                app.Settings.Shift.NBootstrap);
            
            
            CIExcludesZero = ...
                ~( ...
                ShiftCILow <= 0 && ...
                ShiftCIHigh >= 0);
            
            
            BootstrapPass = ...
                BootstrapDirectionStability >= ...
                app.Settings.Shift.MinBootstrapDirectionStability && ...
                CIExcludesZero;
            
            
            % ================================================================
            % DIFFERENCE SPECTRUM
            % ================================================================
            
            medianSpecA = ...
                median( ...
                app.X_norm(searchMask,idxA), ...
                2,'omitnan');
            
            
            medianSpecB = ...
                median( ...
                app.X_norm(searchMask,idxB), ...
                2,'omitnan');
            
            
            differenceCurve = ...
                medianSpecA - ...
                medianSpecB;
            
            
            maskAroundA = ...
                searchWN >= ...
                expectedA - ...
                app.Settings.Shift.DifferenceSupportHalfWindow_cm1 & ...
                searchWN <= ...
                expectedA + ...
                app.Settings.Shift.DifferenceSupportHalfWindow_cm1;
            
            
            maskAroundB = ...
                searchWN >= ...
                expectedB - ...
                app.Settings.Shift.DifferenceSupportHalfWindow_cm1 & ...
                searchWN <= ...
                expectedB + ...
                app.Settings.Shift.DifferenceSupportHalfWindow_cm1;
            
            
            DifferenceOppositeSign = ...
                false;
            
            
            meanDiffA = ...
                NaN;
            
            
            meanDiffB = ...
                NaN;
            
            
            if any(maskAroundA) && ...
                    any(maskAroundB)
                
                meanDiffA = ...
                    mean( ...
                    differenceCurve(maskAroundA), ...
                    'omitnan');
                
                
                meanDiffB = ...
                    mean( ...
                    differenceCurve(maskAroundB), ...
                    'omitnan');
                
                
                DifferenceOppositeSign = ...
                    sign(meanDiffA) ~= ...
                    sign(meanDiffB) && ...
                    sign(meanDiffA) ~= 0 && ...
                    sign(meanDiffB) ~= 0;
                
            end
            
            
            % ================================================================
            % SHIFT PCA
            % ================================================================
            
            PInfo = ...
                app.evaluateShiftPCARegion( ...
                mean([expectedA expectedB]), ...
                actualSearchLow, ...
                actualSearchHigh);
            
            
            % ================================================================
            % EVIDENCE 5 - EXACT OLD LOGIC
            % ================================================================
            
            SpectralPCASupportPass = ...
                DifferenceOppositeSign || ...
                PInfo.Support;
            
            
            % ================================================================
            % CORE SCORE
            % ================================================================
            
            EvidencePass = ...
                [ ...
                AdequateRepresentationPass, ...
                PositionShiftPass, ...
                PositionCompactPass, ...
                BootstrapPass, ...
                SpectralPCASupportPass ...
                ];
            
            
            ScoreNumeric = ...
                sum(EvidencePass);
            
            
            switch ScoreNumeric
                
                case 5
                    
                    ShiftStatus = ...
                        "STRONG SHIFT EVIDENCE";
                    
                    
                case 4
                    
                    ShiftStatus = ...
                        "HIGH PRIORITY SHIFT EVIDENCE";
                    
                    
                case 3
                    
                    ShiftStatus = ...
                        "PROMISING SHIFT EVIDENCE";
                    
                    
                case 2
                    
                    ShiftStatus = ...
                        "REVIEW SHIFT EVIDENCE";
                    
                    
                otherwise
                    
                    ShiftStatus = ...
                        "WEAK SHIFT EVIDENCE";
                    
            end
            
            
            % ================================================================
            % DIRECTION
            % ================================================================
            
            if Shift_B_minus_A > 0
                
                ShiftDirection = ...
                    app.groupB + ...
                    " shifted to higher WN than " + ...
                    app.groupA;
                
                
            elseif Shift_B_minus_A < 0
                
                ShiftDirection = ...
                    app.groupB + ...
                    " shifted to lower WN than " + ...
                    app.groupA;
                
                
            else
                
                ShiftDirection = ...
                    "No median shift";
                
            end
            
            
            % ================================================================
            % RESULT
            % ================================================================
            
            R = struct();
            
            
            R.CandidateID = ...
                candidateID;
            
            
            R.Mode = ...
                "SHIFTED";
            
            
            R.ValidationTime = ...
                app.nowString();
            
            
            R.GroupA = ...
                app.groupA;
            
            
            R.GroupB = ...
                app.groupB;
            
            
            R.ExpectedA = ...
                expectedA;
            
            
            R.ExpectedB = ...
                expectedB;
            
            
            R.SearchLow = ...
                actualSearchLow;
            
            
            R.SearchHigh = ...
                actualSearchHigh;
            
            
            R.DetectedA = ...
                nDetectA;
            
            
            R.DetectedB = ...
                nDetectB;
            
            
            R.NA = ...
                nA;
            
            
            R.NB = ...
                nB;
            
            
            R.PrevalenceA = ...
                prevA;
            
            
            R.PrevalenceB = ...
                prevB;
            
            
            R.DetectionAgreementA = ...
                DetectionAgreementA;
            
            
            R.DetectionAgreementB = ...
                DetectionAgreementB;
            
            
            R.NormUnnormDetectionAgreement = ...
                DetectionAgreementOverall;
            
            
            R.MedianPeakA = ...
                medianA;
            
            
            R.MedianPeakB = ...
                medianB;
            
            
            R.Shift_B_minus_A = ...
                Shift_B_minus_A;
            
            
            R.AbsoluteShift = ...
                AbsShift;
            
            
            R.Direction = ...
                ShiftDirection;
            
            
            R.PositionIQR_A = ...
                IQR_A;
            
            
            R.PositionIQR_B = ...
                IQR_B;
            
            
            R.PositionMAD_A = ...
                MAD_A;
            
            
            R.PositionMAD_B = ...
                MAD_B;
            
            
            R.PositionP = ...
                pPosition;
            
            
            R.PositionEffect = ...
                PositionEffect;
            
            
            R.Bootstrap = ...
                BootstrapDirectionStability;
            
            
            R.CILow = ...
                ShiftCILow;
            
            
            R.CIHigh = ...
                ShiftCIHigh;
            
            
            R.CIExcludesZero = ...
                CIExcludesZero;
            
            
            R.DifferenceMeanA = ...
                meanDiffA;
            
            
            R.DifferenceMeanB = ...
                meanDiffB;
            
            
            R.DifferenceOppositeSign = ...
                DifferenceOppositeSign;
            
            
            R.PCADomain = ...
                PInfo.Domain;
            
            
            R.BestPC = ...
                PInfo.BestPC;
            
            
            R.SecondPC = ...
                PInfo.SecondPC;
            
            
            R.PCScoreEffect = ...
                PInfo.ScoreEffect;
            
            
            R.PCALoadingLevel = ...
                PInfo.LoadingLevel;
            
            
            R.PCALocalExtremum = ...
                PInfo.LocalExtremum;
            
            
            R.PCALoadingSupport = ...
                PInfo.Support;
            
            
            R.LoadingExtremumWN = ...
                PInfo.LoadingExtremumWN;
            
            
            R.EvidenceRepresentation = ...
                AdequateRepresentationPass;
            
            
            R.EvidenceShiftMagnitude = ...
                PositionShiftPass;
            
            
            R.EvidenceCompactness = ...
                PositionCompactPass;
            
            
            R.EvidenceBootstrapCI = ...
                BootstrapPass;
            
            
            R.EvidenceDifferencePCA = ...
                SpectralPCASupportPass;
            
            
            R.Score = ...
                ScoreNumeric;
            
            
            R.Status = ...
                ShiftStatus;
            
            
            R.Decision = ...
                "PENDING";
            
            
            R.PositionA = ...
                posA;
            
            
            R.PositionB = ...
                posB;
            
            
            R.BootstrapShift = ...
                bootShift;
            
            
            R.DifferenceCurve = ...
                differenceCurve;
            
            
            R.SearchWN = ...
                searchWN;
            
            
            R.PCAInfo = ...
                PInfo;
            
            
            % ================================================================
            % FOUR AXES
            % ================================================================
            
            if doPlot
                app.plotShiftValidationFourAxes(R);
            end
            
        end
        
        
        function R = detectShiftPeakSet( ...
                app, ...
                X, ...
                searchMask, ...
                expectedWN)
            
            % ================================================================
            % SHIFT PEAK DETECTOR
            %
            % Preserved from shifted_check.m
            %
            % For every spectrum:
            %
            % 1 local linear detrend
            % 2 robust noise from first difference
            % 3 true local maxima
            % 4 morphology test
            % 5 prominence
            % 6 prominence SNR
            % 7 prominence-distance score
            % 8 quadratic sub-grid refinement
            % ================================================================
            
            wn = ...
                double(app.wn(:));
            
            
            nSpec = ...
                size(X,2);
            
            
            shapeHalfWindow = ...
                app.Settings.Shift.ShapeHalfWindow_cm1;
            
            
            snrThreshold = ...
                app.Settings.Shift.PrimarySNRThreshold;
            
            
            slopeNeighbourPoints = ...
                app.Settings.Shift.SlopeNeighbourPoints;
            
            
            minDirectionalFraction = ...
                app.Settings.Shift.MinDirectionalFraction;
            
            
            PeakWN = ...
                NaN(nSpec,1);
            
            
            RefinedPeakWN = ...
                NaN(nSpec,1);
            
            
            Prominence = ...
                NaN(nSpec,1);
            
            
            ProminenceSNR = ...
                NaN(nSpec,1);
            
            
            Detected = ...
                false(nSpec,1);
            
            
            for k = 1:nSpec
                
                yFull = ...
                    double(X(:,k));
                
                
                localMask = ...
                    searchMask & ...
                    wn >= expectedWN-shapeHalfWindow & ...
                    wn <= expectedWN+shapeHalfWindow;
                
                
                idx = ...
                    find(localMask);
                
                
                if numel(idx) < 5
                    
                    continue
                    
                end
                
                
                x = ...
                    wn(idx);
                
                
                y = ...
                    yFull(idx);
                
                
                if any(~isfinite(y))
                    
                    continue
                    
                end
                
                
                % ================================================================
                % LOCAL LINEAR DETREND
                % ================================================================
                
                p = ...
                    polyfit( ...
                    x, ...
                    y, ...
                    1);
                
                
                baseline = ...
                    polyval( ...
                    p, ...
                    x);
                
                
                residual = ...
                    y - ...
                    baseline;
                
                
                % ================================================================
                % ROBUST LOCAL NOISE
                % ================================================================
                
                d = ...
                    diff(residual);
                
                
                dMedian = ...
                    median(d);
                
                
                dMAD = ...
                    median( ...
                    abs(d-dMedian));
                
                
                sigmaNoise = ...
                    1.4826 .* ...
                    dMAD ./ ...
                    sqrt(2);
                
                
                if sigmaNoise <= eps || ...
                        ~isfinite(sigmaNoise)
                    
                    sigmaNoise = ...
                        std( ...
                        d, ...
                        0, ...
                        'omitnan') ./ ...
                        sqrt(2);
                    
                end
                
                
                if sigmaNoise <= eps || ...
                        ~isfinite(sigmaNoise)
                    
                    sigmaNoise = ...
                        eps;
                    
                end
                
                
                % ================================================================
                % TRUE LOCAL MAXIMA
                % ================================================================
                
                maxima = ...
                    find( ...
                    islocalmax( ...
                    residual, ...
                    'FlatSelection','center'));
                
                
                if isempty(maxima)
                    
                    continue
                    
                end
                
                
                bestDistance = ...
                    Inf;
                
                bestProminence = ...
                    -Inf;
                
                bestIdx = ...
                    NaN;
                
                bestProm = ...
                    NaN;
                
                
                % ================================================================
                % EVALUATE EVERY MAXIMUM
                % ================================================================
                
                for j = 1:numel(maxima)
                    
                    ip = ...
                        maxima(j);
                    
                    
                    if ip <= 1 || ...
                            ip >= numel(x)
                        
                        continue
                        
                    end
                    
                    
                    % ------------------------------------------------------------
                    % MORPHOLOGY
                    % ------------------------------------------------------------
                    
                    leftStart = ...
                        max( ...
                        1, ...
                        ip-slopeNeighbourPoints);
                    
                    
                    rightEnd = ...
                        min( ...
                        numel(x), ...
                        ip+slopeNeighbourPoints);
                    
                    
                    leftDiff = ...
                        diff( ...
                        residual(leftStart:ip));
                    
                    
                    rightDiff = ...
                        diff( ...
                        residual(ip:rightEnd));
                    
                    
                    if isempty(leftDiff) || ...
                            isempty(rightDiff)
                        
                        continue
                        
                    end
                    
                    
                    leftRiseFraction = ...
                        mean( ...
                        leftDiff > 0);
                    
                    
                    rightFallFraction = ...
                        mean( ...
                        rightDiff < 0);
                    
                    
                    if leftRiseFraction < ...
                            minDirectionalFraction || ...
                            rightFallFraction < ...
                            minDirectionalFraction
                        
                        continue
                        
                    end
                    
                    
                    % ------------------------------------------------------------
                    % LOCAL PROMINENCE
                    % ------------------------------------------------------------
                    
                    leftMinimum = ...
                        min( ...
                        residual(1:ip));
                    
                    
                    rightMinimum = ...
                        min( ...
                        residual(ip:end));
                    
                    
                    localBase = ...
                        max( ...
                        leftMinimum, ...
                        rightMinimum);
                    
                    
                    thisProminence = ...
                        residual(ip) - ...
                        localBase;
                    
                    
                    if thisProminence <= 0
                        
                        continue
                        
                    end
                    
                    
                    thisSNR = ...
                        thisProminence ./ ...
                        sigmaNoise;
                    
                    
                    if thisSNR < ...
                            snrThreshold
                        
                        continue
                        
                    end
                    
                    
                    % ------------------------------------------------------------
                    % MATCH TO EXPECTED BAND
                    %
                    % Primary criterion:
                    % nearest valid local peak to expected WN.
                    %
                    % Tie-break:
                    % higher prominence.
                    % ------------------------------------------------------------
                    
                    thisDistance = ...
                        abs(x(ip)-expectedWN);
                    
                    if thisDistance < bestDistance
                        
                        bestDistance = ...
                            thisDistance;
                        
                        bestProminence = ...
                            thisProminence;
                        
                        bestIdx = ...
                            ip;
                        
                        bestProm = ...
                            thisProminence;
                        
                    elseif abs(thisDistance-bestDistance) <= 1e-9
                        
                        if thisProminence > bestProminence
                            
                            bestProminence = ...
                                thisProminence;
                            
                            bestIdx = ...
                                ip;
                            
                            bestProm = ...
                                thisProminence;
                        end
                    end
                    
                end
                
                
                if ~isfinite(bestIdx)
                    
                    continue
                    
                end
                
                
                rawPeakWN = ...
                    x(bestIdx);
                
                
                % ================================================================
                % SUB-GRID QUADRATIC REFINEMENT
                % ================================================================
                
                refinedWN = ...
                    rawPeakWN;
                
                
                if bestIdx > 1 && ...
                        bestIdx < numel(x)
                    
                    x3 = ...
                        x( ...
                        bestIdx-1:bestIdx+1);
                    
                    
                    y3 = ...
                        residual( ...
                        bestIdx-1:bestIdx+1);
                    
                    
                    q = ...
                        polyfit( ...
                        x3, ...
                        y3, ...
                        2);
                    
                    
                    if isfinite(q(1)) && ...
                            isfinite(q(2)) && ...
                            abs(q(1)) > eps && ...
                            q(1) < 0
                        
                        vertexWN = ...
                            -q(2) ./ ...
                            (2.*q(1));
                        
                        
                        if vertexWN >= ...
                                min(x3) && ...
                                vertexWN <= ...
                                max(x3)
                            
                            refinedWN = ...
                                vertexWN;
                            
                        end
                        
                    end
                    
                end
                
                
                PeakWN(k) = ...
                    rawPeakWN;
                
                
                RefinedPeakWN(k) = ...
                    refinedWN;
                
                
                Prominence(k) = ...
                    bestProm;
                
                
                ProminenceSNR(k) = ...
                    bestProm ./ ...
                    sigmaNoise;
                
                
                Detected(k) = ...
                    true;
                
            end
            
            
            R = struct();
            
            
            R.PeakWN = ...
                PeakWN;
            
            
            R.RefinedPeakWN = ...
                RefinedPeakWN;
            
            
            R.Prominence = ...
                Prominence;
            
            
            R.ProminenceSNR = ...
                ProminenceSNR;
            
            
            R.Detected = ...
                Detected;
            
        end


        function I = evaluateSharedPCARegion( ...
                app, ...
                centerWN, ...
                regionLow, ...
                regionHigh)
            
            % ================================================================
            % EXACT SHARED PCA LOGIC FROM analysis_main4_confirm
            %
            % PASS ONLY WHEN:
            %
            % max |loading| inside ROI
            % >= percentile threshold of the FULL loading curve
            %
            % No MODERATE rule.
            % No local-extremum shortcut.
            % ================================================================
            
            if centerWN >= 400 && ...
                    centerWN <= 1800
                
                P = app.PCA_Fingerprint;
                
                domain = "Fingerprint";
                
            elseif centerWN >= 2800 && ...
                    centerWN <= 3100
                
                P = app.PCA_HighWN;
                
                domain = "High-WN";
                
            else
                
                P = app.PCA_Full;
                
                domain = "Full";
                
            end
            
            
            gid = string(P.GroupID(:));
            
            idxA = gid == app.groupA;
            
            idxB = gid == app.groupB;
            
            
            if numel(gid) ~= ...
                    numel(app.analysisGroupID)
                
                error( ...
                    ['PCA group vector size does not match current spectra. ' ...
                    'Recalculate PCA from current X_norm.']);
                
            end
            
            
            nPCcheck = ...
                min( ...
                3, ...
                size(P.Scores,2));
            
            
            if nPCcheck < 2
                
                error( ...
                    'Selected PCA model contains fewer than 2 PCs.');
                
            end
            
            
            % ================================================================
            % SCORE EFFECTS
            % ================================================================
            
            scoreEffects = ...
                NaN(nPCcheck,1);
            
            
            for pc = 1:nPCcheck
                
                scoreEffects(pc) = ...
                    app.robustEffectLocal( ...
                    P.Scores(idxA,pc), ...
                    P.Scores(idxB,pc));
                
            end
            
            
            [~,orderPC] = ...
                sort( ...
                abs(scoreEffects), ...
                'descend');
            
            
            bestPC = ...
                orderPC(1);
            
            
            secondPC = ...
                orderPC(2);
            
            
            % ================================================================
            % LOADING SUPPORT
            % ================================================================
            
            pcaWN = ...
                double(P.Wavenumber(:));
            
            
            loadingCurve = ...
                double(P.Loadings(:,bestPC));
            
            
            loadingROIMask = ...
                pcaWN >= regionLow & ...
                pcaWN <= regionHigh;
            
            
            if any(loadingROIMask)
                
                localLoading = ...
                    loadingCurve(loadingROIMask);
                
                
                localLoadingWN = ...
                    pcaWN(loadingROIMask);
                
                
                [maxAbsLoading,idxL] = ...
                    max( ...
                    abs(localLoading));
                
                
                loadingExtremumWN = ...
                    localLoadingWN(idxL);
                
                
                loadingThreshold = ...
                    prctile( ...
                    abs(loadingCurve), ...
                    app.Settings.Shared.PCALoadingPercentile);
                
                
                PCALoadingSupport = ...
                    maxAbsLoading >= ...
                    loadingThreshold;
                
            else
                
                maxAbsLoading = ...
                    NaN;
                
                
                loadingExtremumWN = ...
                    NaN;
                
                
                loadingThreshold = ...
                    NaN;
                
                
                PCALoadingSupport = ...
                    false;
                
            end
            
            
            % ================================================================
            % OUTPUT
            % ================================================================
            
            I = struct();
            
            I.Model = P;
            
            I.Domain = domain;
            
            I.BestPC = bestPC;
            
            I.SecondPC = secondPC;
            
            I.ScoreEffects = scoreEffects;
            
            I.ScoreEffect = ...
                scoreEffects(bestPC);
            
            I.LoadingCurve = ...
                loadingCurve;
            
            I.LoadingExtremumWN = ...
                loadingExtremumWN;
            
            I.MaxLoading = ...
                maxAbsLoading;
            
            I.LoadingThreshold = ...
                loadingThreshold;
            
            I.LoadingPercentile = ...
                app.Settings.Shared.PCALoadingPercentile;
            
            I.Support = ...
                logical(PCALoadingSupport);
            
        end


        function effect = robustEffectLocal(app,A,B)
            
            %#ok<INUSD>
            
            
            A = ...
                double(A(:));
            
            
            B = ...
                double(B(:));
            
            
            A = ...
                A(isfinite(A));
            
            
            B = ...
                B(isfinite(B));
            
            
            if isempty(A) || ...
                    isempty(B)
                
                effect = ...
                    NaN;
                
                return
                
            end
            
            
            medA = ...
                median(A);
            
            
            medB = ...
                median(B);
            
            
            sigmaA = ...
                1.4826 .* ...
                median( ...
                abs(A-medA));
            
            
            sigmaB = ...
                1.4826 .* ...
                median( ...
                abs(B-medB));
            
            
            pooled = ...
                sqrt( ...
                (sigmaA.^2 + ...
                sigmaB.^2)./2);
            
            
            if pooled <= eps
                
                if abs(medA-medB) <= eps
                    
                    effect = ...
                        0;
                    
                else
                    
                    effect = ...
                        sign(medA-medB).*Inf;
                    
                end
                
            else
                
                effect = ...
                    (medA-medB)./pooled;
                
            end
            
        end


        function r = relativeIQRlocal(app,x)
            
            %#ok<INUSD>
            
            
            x = ...
                double(x(:));
            
            
            x = ...
                x(isfinite(x));
            
            
            if isempty(x)
                
                r = ...
                    NaN;
                
                return
                
            end
            
            
            r = ...
                iqr(x) ./ ...
                max( ...
                abs(median(x)), ...
                eps);
            
        end


        function stability = bootstrapDirectionStability( ...
                app,A,B,nBoot)
            
            %#ok<INUSD>
            
            
            A = ...
                double(A(:));
            
            
            B = ...
                double(B(:));
            
            
            A = ...
                A(isfinite(A));
            
            
            B = ...
                B(isfinite(B));
            
            
            if numel(A) < 3 || ...
                    numel(B) < 3
                
                stability = ...
                    NaN;
                
                return
                
            end
            
            
            observed = ...
                sign( ...
                median(A) - ...
                median(B));
            
            
            if observed == 0
                
                stability = ...
                    0.5;
                
                return
                
            end
            
            
            nSame = ...
                0;
            
            
            for b = 1:nBoot
                
                Ab = ...
                    A( ...
                    randi( ...
                    numel(A), ...
                    numel(A),1));
                
                
                Bb = ...
                    B( ...
                    randi( ...
                    numel(B), ...
                    numel(B),1));
                
                
                if sign( ...
                        median(Ab) - ...
                        median(Bb)) == observed
                    
                    nSame = ...
                        nSame+1;
                    
                end
                
            end
            
            
            stability = ...
                nSame/nBoot;
            
        end


        function [bootShift,stability,ciLow,ciHigh] = ...
                bootstrapShiftDifference( ...
                app,A,B,nBoot)
            
            %#ok<INUSD>
            
            
            A = ...
                double(A(:));
            
            
            B = ...
                double(B(:));
            
            
            A = ...
                A(isfinite(A));
            
            
            B = ...
                B(isfinite(B));
            
            
            observed = ...
                median(B) - ...
                median(A);
            
            
            observedDirection = ...
                sign(observed);
            
            
            bootShift = ...
                NaN(nBoot,1);
            
            
            for b = 1:nBoot
                
                Ab = ...
                    A( ...
                    randi( ...
                    numel(A), ...
                    numel(A),1));
                
                
                Bb = ...
                    B( ...
                    randi( ...
                    numel(B), ...
                    numel(B),1));
                
                
                bootShift(b) = ...
                    median(Bb) - ...
                    median(Ab);
                
            end
            
            
            if observedDirection == 0
                
                stability = ...
                    0.5;
                
            else
                
                stability = ...
                    mean( ...
                    sign(bootShift) == ...
                    observedDirection);
                
            end
            
            
            ci = ...
                prctile( ...
                bootShift, ...
                [2.5 97.5]);
            
            
            ciLow = ...
                ci(1);
            
            
            ciHigh = ...
                ci(2);
            
        end


        function p = safeRanksum(app,A,B)
            
            %#ok<INUSD>
            
            
            A = ...
                A(isfinite(A));
            
            
            B = ...
                B(isfinite(B));
            
            
            if numel(A) < 2 || ...
                    numel(B) < 2
                
                p = ...
                    NaN;
                
                return
                
            end
            
            
            p = ...
                ranksum(A,B);
            
        end


        function s = sharedEvidenceStatus(app,score)
            
            %#ok<INUSD>
            
            
            switch score
                
                case 5
                    s = "STRONG SPECTRAL EVIDENCE";
                    
                case 4
                    s = "HIGH PRIORITY SPECTRAL EVIDENCE";
                    
                case 3
                    s = "PROMISING SPECTRAL EVIDENCE";
                    
                case 2
                    s = "REVIEW";
                    
                otherwise
                    s = "WEAK SPECTRAL EVIDENCE";
                    
            end
            
        end


        function s = shiftEvidenceStatus(app,score)
            
            %#ok<INUSD>
            
            
            switch score
                
                case 5
                    s = "STRONG SHIFT EVIDENCE";
                    
                case 4
                    s = "HIGH PRIORITY SHIFT EVIDENCE";
                    
                case 3
                    s = "PROMISING SHIFT EVIDENCE";
                    
                case 2
                    s = "REVIEW SHIFT EVIDENCE";
                    
                otherwise
                    s = "WEAK SHIFT EVIDENCE";
                    
            end
            
        end


        function plotTwoGroupBox(app,ax,A,B,gA,gB)
            
            %#ok<INUSD>
            
            % Full reset is essential because the same UIAxes
            % is reused later for Raman spectra.
            cla(ax,'reset');
            
            A = double(A(:));
            B = double(B(:));
            
            A = A(isfinite(A));
            B = B(isfinite(B));
            
            hold(ax,'on');
            
            groupColors = lines(2);
            colorA = groupColors(1,:);
            colorB = groupColors(2,:);
            
            boxchart( ...
                ax, ...
                ones(size(A)), ...
                A, ...
                'BoxFaceColor',colorA, ...
                'MarkerStyle','none');
            
            boxchart( ...
                ax, ...
                2.*ones(size(B)), ...
                B, ...
                'BoxFaceColor',colorB, ...
                'MarkerStyle','none');
            
            scatter( ...
                ax, ...
                1 + 0.14.*(rand(size(A))-0.5), ...
                A, ...
                14, ...
                colorA, ...
                'filled', ...
                'MarkerFaceAlpha',0.25, ...
                'HandleVisibility','off');
            
            scatter( ...
                ax, ...
                2 + 0.14.*(rand(size(B))-0.5), ...
                B, ...
                14, ...
                colorB, ...
                'filled', ...
                'MarkerFaceAlpha',0.25, ...
                'HandleVisibility','off');
            
            hold(ax,'off');
            
            xlim(ax,[0.5 2.5]);
            
            xticks(ax,[1 2]);
            
            xticklabels( ...
                ax, ...
                {char(gA),char(gB)});
            
            box(ax,'on');
            grid(ax,'off');
            
        end


        function plotPCAEvidenceOnAxes( ...
                app,ax,I,roi,targetWN)
            
            %#ok<INUSD>
            
            
            cla(ax);
            
            
            P = ...
                I.Model;
            
            
            pc = ...
                I.BestPC;
            
            
            plot( ...
                ax, ...
                P.Wavenumber, ...
                P.Loadings(:,pc), ...
                'LineWidth',1.3);
            
            
            hold(ax,'on');
            
            
            yline( ...
                ax,0,'--');
            
            
            xline( ...
                ax,roi(1),':');
            
            
            xline( ...
                ax,roi(2),':');
            
            
            xline( ...
                ax,targetWN,'--');
            
            
            if isfinite(I.LoadingExtremumWN)
                
                xline( ...
                    ax, ...
                    I.LoadingExtremumWN, ...
                    '-.');
                
            end
            
            
            hold(ax,'off');
            
            
            xlabel( ...
                ax, ...
                'Raman shift (cm^{-1})');
            
            
            ylabel( ...
                ax, ...
                sprintf( ...
                'PC%d loading', ...
                pc));
            
            
            title( ...
                ax, ...
                sprintf( ...
                '%s | PC%d | loading %s', ...
                I.Domain, ...
                pc, ...
                I.LoadingLevel), ...
                'Interpreter','none');
            
            
            box(ax,'on');
            
        end
        
        
        function txt = passText(app,tf)
            
            %#ok<INUSD>
            
            
            if logical(tf)
                
                txt = ...
                    "PASS";
                
            else
                
                txt = ...
                    "FAIL";
                
            end
            
        end


        function showAppError(app,ME,titleText)
            
            fprintf( ...
                2, ...
                '%s\n', ...
                getReport( ...
                ME, ...
                'extended', ...
                'hyperlinks','off'));
            
            
            uialert( ...
                app.UIFigure, ...
                ME.message, ...
                titleText, ...
                'Icon','error');
            
        end

        function safeName = makeSafeFileName(app,nameIn)
            
            %#ok<INUSD>
            
            % ================================================================
            % CREATE WINDOWS-SAFE FILE / FOLDER NAME
            % ================================================================
            
            safeName = ...
                strtrim( ...
                char( ...
                string(nameIn)));
            
            
            % Windows-invalid filename characters
            badChars = ...
                { ...
                '<', ...
                '>', ...
                ':', ...
                '"', ...
                '/', ...
                '\', ...
                '|', ...
                '?', ...
                '*' ...
                };
            
            
            for k = 1:numel(badChars)
                
                safeName = ...
                    strrep( ...
                    safeName, ...
                    badChars{k}, ...
                    '_');
                
            end
            
            
            % Remove control characters
            safeName = ...
                regexprep( ...
                safeName, ...
                '[\x00-\x1F]', ...
                '_');
            
            
            % Avoid repeated underscores
            safeName = ...
                regexprep( ...
                safeName, ...
                '_+', ...
                '_');
            
            
            % Windows does not like folder/file names ending in dot or space
            safeName = ...
                regexprep( ...
                safeName, ...
                '[\. ]+$', ...
                '');
            
            
            % Avoid leading spaces/dots
            safeName = ...
                regexprep( ...
                safeName, ...
                '^[\. ]+', ...
                '');
            
            
            if isempty(safeName)
                
                safeName = ...
                    'CandidateAnalysis';
                
            end
            
        end


        function s = nowString(app)
            
            %#ok<INUSD>
            
            s = ...
                string( ...
                datetime( ...
                'now', ...
                'Format','yyyy-MM-dd HH:mm:ss'));
            
        end


        function seed = seedFromText(app,baseSeed,txt)
            
            %#ok<INUSD>
            
            v = ...
                double( ...
                char( ...
                string(txt)));
            
            
            idx = ...
                1:numel(v);
            
            
            seed = ...
                mod( ...
                double(baseSeed) + ...
                sum(v .* idx), ...
                2^31 - 1);
            
            
            if seed < 1
                
                seed = ...
                    1;
                
            end
            
            
            seed = ...
                floor(seed);
            
        end


        function value = firstExplained(app,P,pc)
            
            %#ok<INUSD>
            
            value = ...
                NaN;
            
            
            if isempty(P) || ...
                    ~isstruct(P) || ...
                    ~isfield(P,'Explained')
                
                return
                
            end
            
            
            if pc >= 1 && ...
                    pc <= numel(P.Explained)
                
                value = ...
                    double( ...
                    P.Explained(pc));
                
            end
            
        end
        
        
        function safeExportFigure(app,figHandle,filePath)
            
            try
                
                if isempty(figHandle) || ...
                        ~isgraphics(figHandle)
                    
                    return
                    
                end
                
                
                folder = ...
                    fileparts(filePath);
                
                
                if ~isempty(folder) && ...
                        ~isfolder(folder)
                    
                    mkdir(folder);
                    
                end
                
                
                exportgraphics( ...
                    figHandle, ...
                    filePath, ...
                    'Resolution', ...
                    app.Settings.Output.FigureResolution);
                
                
            catch ME
                
                warning( ...
                    'Could not export figure "%s": %s', ...
                    filePath, ...
                    ME.message);
                
            end
            
        end


        function updateCandidateValidationTable(app,R)
            
            if isempty(app.UITable.Data) || ...
                    ~istable(app.UITable.Data)
                
                return
                
            end
            
            
            row = ...
                app.SelectedCandidateRow;
            
            
            if ~isfinite(row) || ...
                    row < 1 || ...
                    row > height(app.UITable.Data)
                
                return
                
            end
            
            
            app.UITable.Data.Validation(row) = ...
                sprintf( ...
                'VALIDATED | %d/5 | %s', ...
                R.Score, ...
                R.Status);
            
            
            % Validation itself does not change review decision.
            % Decision remains PENDING until Confirm candidate is pressed.
            
            if app.UITable.Data.Decision(row) == ""
                
                app.UITable.Data.Decision(row) = ...
                    "PENDING";
                
            end
            
            
            drawnow;
            
        end


        function showValidationText(app,R)
            
            if isempty(R) || ...
                    ~isstruct(R)
                
                return
                
            end
            
            
            mode = ...
                upper(string(R.Mode));
            
            
            % ================================================================
            % SHARED
            % ================================================================
            
            if mode == "SHARED"
                
                lines = ...
                    { ...
                    'SHARED CANDIDATE VALIDATION'; ...
                    '────────────────────────────────'; ...
                    ''; ...
                    sprintf('Candidate: %s',R.CandidateID); ...
                    sprintf('Validation time: %s',R.ValidationTime); ...
                    sprintf('Group A: %s',R.GroupA); ...
                    sprintf('Group B: %s',R.GroupB); ...
                    ''; ...
                    sprintf('Representative WN: %.3f cm^-1',R.WN); ...
                    sprintf('ROI: %.3f - %.3f cm^-1',R.ROILow,R.ROIHigh); ...
                    sprintf('Primary measurement: %s',R.PrimaryMeasurement); ...
                    sprintf('Trend: %s',R.Direction); ...
                    ''; ...
                    'PRIMARY FEATURE'; ...
                    '────────────────────────────────'; ...
                    sprintf('Robust effect normalized: %.4f',R.EffectNorm); ...
                    sprintf('Robust effect unnormalized: %.4f',R.EffectUnnorm); ...
                    sprintf('Relative IQR A: %.4f',R.RelativeIQR_A); ...
                    sprintf('Relative IQR B: %.4f',R.RelativeIQR_B); ...
                    sprintf('Mann-Whitney p normalized: %.4g',R.P_Norm); ...
                    sprintf('Mann-Whitney p unnormalized: %.4g',R.P_Unnorm); ...
                    sprintf('Bootstrap direction stability: %.2f %%',100*R.Bootstrap); ...
                    sprintf('Normalization direction agreement: %s', ...
                    app.passText(R.DirectionAgreement)); ...
                    ''; ...
                    'PCA'; ...
                    '────────────────────────────────'; ...
                    sprintf('PCA domain: %s',R.PCADomain); ...
                    sprintf('Best PC: PC%d',R.BestPC); ...
                    sprintf('Second PC: PC%d',R.SecondPC); ...
                    sprintf('Best-PC score robust effect: %.4f',R.PCScoreEffect); ...
                    sprintf('Loading level: %s',R.LoadingLevel); ...
                    sprintf('Loading support: %s', ...
                    app.passText(R.PCALoadingSupport)); ...
                    sprintf('Loading extremum: %.3f cm^-1',R.LoadingExtremumWN); ...
                    ''; ...
                    'CORE EVIDENCE'; ...
                    '────────────────────────────────'; ...
                    sprintf('1 Effect support             : %s', ...
                    app.passText(R.EvidenceEffect)); ...
                    sprintf('2 Distribution support       : %s', ...
                    app.passText(R.EvidenceDistribution)); ...
                    sprintf('3 Bootstrap support          : %s', ...
                    app.passText(R.EvidenceBootstrap)); ...
                    sprintf('4 Normalization support      : %s', ...
                    app.passText(R.EvidenceNormalization)); ...
                    sprintf('5 PCA loading support        : %s', ...
                    app.passText(R.EvidencePCA)); ...
                    ''; ...
                    sprintf('EVIDENCE SCORE: %d/5',R.Score); ...
                    sprintf('STATE: %s',R.Status); ...
                    sprintf('DECISION: %s',R.Decision); ...
                    ''; ...
                    'The evidence score summarizes predefined criteria.'; ...
                    'It is not a probability that this feature is a biomarker.' ...
                    };
                
                
                % ================================================================
                % SHIFTED
                % ================================================================
                
            elseif mode == "SHIFTED"
                
                lines = ...
                    { ...
                    'SHIFTED PEAK VALIDATION'; ...
                    '────────────────────────────────'; ...
                    ''; ...
                    sprintf('Candidate: %s',R.CandidateID); ...
                    sprintf('Validation time: %s',R.ValidationTime); ...
                    sprintf('Group A: %s',R.GroupA); ...
                    sprintf('Group B: %s',R.GroupB); ...
                    ''; ...
                    sprintf('Expected A: %.3f cm^-1',R.ExpectedA); ...
                    sprintf('Expected B: %.3f cm^-1',R.ExpectedB); ...
                    sprintf('Search region: %.3f - %.3f cm^-1', ...
                    R.SearchLow,R.SearchHigh); ...
                    ''; ...
                    'REPRESENTATION'; ...
                    '────────────────────────────────'; ...
                    sprintf('%s: %.1f %% (%d/%d detected)', ...
                    R.GroupA,100*R.PrevalenceA,R.DetectedA,R.NA); ...
                    sprintf('%s: %.1f %% (%d/%d detected)', ...
                    R.GroupB,100*R.PrevalenceB,R.DetectedB,R.NB); ...
                    sprintf('Norm/unnorm detection agreement: %.1f %%', ...
                    100*R.NormUnnormDetectionAgreement); ...
                    ''; ...
                    'PEAK POSITION'; ...
                    '────────────────────────────────'; ...
                    sprintf('Median peak A: %.3f cm^-1',R.MedianPeakA); ...
                    sprintf('Median peak B: %.3f cm^-1',R.MedianPeakB); ...
                    sprintf('Shift B - A: %+.3f cm^-1',R.Shift_B_minus_A); ...
                    sprintf('Absolute shift: %.3f cm^-1',R.AbsoluteShift); ...
                    sprintf('Direction: %s',R.Direction); ...
                    sprintf('Position IQR A: %.3f',R.PositionIQR_A); ...
                    sprintf('Position IQR B: %.3f',R.PositionIQR_B); ...
                    sprintf('Position MAD A: %.3f',R.PositionMAD_A); ...
                    sprintf('Position MAD B: %.3f',R.PositionMAD_B); ...
                    sprintf('Position Mann-Whitney p: %.4g',R.PositionP); ...
                    sprintf('Position robust effect: %.4f',R.PositionEffect); ...
                    ''; ...
                    'BOOTSTRAP'; ...
                    '────────────────────────────────'; ...
                    sprintf('Bootstrap direction stability: %.2f %%', ...
                    100*R.Bootstrap); ...
                    sprintf('95%% CI: %.3f to %.3f cm^-1', ...
                    R.CILow,R.CIHigh); ...
                    sprintf('CI excludes zero: %s', ...
                    app.passText(R.CIExcludesZero)); ...
                    ''; ...
                    'SPECTRAL / PCA SUPPORT'; ...
                    '────────────────────────────────'; ...
                    sprintf('Difference opposite-sign support: %s', ...
                    app.passText(R.DifferenceOppositeSign)); ...
                    sprintf('PCA domain: %s',R.PCADomain); ...
                    sprintf('Best PC: PC%d',R.BestPC); ...
                    sprintf('PC score robust effect: %.4f',R.PCScoreEffect); ...
                    sprintf('PCA loading level: %s',R.PCALoadingLevel); ...
                    sprintf('PCA loading support: %s', ...
                    app.passText(R.PCALoadingSupport)); ...
                    sprintf('Loading extremum: %.3f cm^-1',R.LoadingExtremumWN); ...
                    ''; ...
                    'CORE EVIDENCE'; ...
                    '────────────────────────────────'; ...
                    sprintf('1 Adequate representation  : %s', ...
                    app.passText(R.EvidenceRepresentation)); ...
                    sprintf('2 Shift magnitude           : %s', ...
                    app.passText(R.EvidenceShiftMagnitude)); ...
                    sprintf('3 Position compactness      : %s', ...
                    app.passText(R.EvidenceCompactness)); ...
                    sprintf('4 Bootstrap + CI            : %s', ...
                    app.passText(R.EvidenceBootstrapCI)); ...
                    sprintf('5 Difference / PCA support  : %s', ...
                    app.passText(R.EvidenceDifferencePCA)); ...
                    ''; ...
                    sprintf('EVIDENCE SCORE: %d/5',R.Score); ...
                    sprintf('STATE: %s',R.Status); ...
                    sprintf('DECISION: %s',R.Decision); ...
                    };
                
            elseif mode == "FWHM"
                
                lines = { ...
                    'FWHM / WIDTH CANDIDATE VALIDATION'; ...
                    '────────────────────────────────'; ...
                    ''; ...
                    sprintf('Candidate: %s',R.CandidateID); ...
                    sprintf('Validation time: %s',R.ValidationTime); ...
                    sprintf('Group A: %s',R.GroupA); ...
                    sprintf('Group B: %s',R.GroupB); ...
                    ''; ...
                    sprintf('Expected position A: %.3f cm^-1',R.ExpectedA); ...
                    sprintf('Expected position B: %.3f cm^-1',R.ExpectedB); ...
                    sprintf('Search region: %.3f - %.3f cm^-1', ...
                    R.SearchLow,R.SearchHigh); ...
                    ''; ...
                    'REPRESENTATION'; ...
                    '────────────────────────────────'; ...
                    sprintf('%s: %.1f %% (%d/%d)', ...
                    R.GroupA,100*R.PrevalenceA,R.DetectedA,R.NA); ...
                    sprintf('%s: %.1f %% (%d/%d)', ...
                    R.GroupB,100*R.PrevalenceB,R.DetectedB,R.NB); ...
                    ''; ...
                    'WIDTH'; ...
                    '────────────────────────────────'; ...
                    sprintf('Median FWHM A: %.3f cm^-1',R.MedianWidthA); ...
                    sprintf('Median FWHM B: %.3f cm^-1',R.MedianWidthB); ...
                    sprintf('FWHM B-A: %+.3f cm^-1', ...
                    R.WidthDifference_B_minus_A); ...
                    sprintf('Direction: %s',R.Direction); ...
                    sprintf('Robust width effect: %.4f',R.WidthEffect); ...
                    sprintf('Relative IQR A: %.4f',R.RelativeIQR_A); ...
                    sprintf('Relative IQR B: %.4f',R.RelativeIQR_B); ...
                    sprintf('Mann-Whitney p: %.4g',R.WidthP); ...
                    ''; ...
                    'BOOTSTRAP'; ...
                    '────────────────────────────────'; ...
                    sprintf('Direction stability: %.2f %%', ...
                    100*R.Bootstrap); ...
                    sprintf('95%% CI: %.3f to %.3f cm^-1', ...
                    R.CILow,R.CIHigh); ...
                    sprintf('CI excludes zero: %s', ...
                    app.passText(R.CIExcludesZero)); ...
                    ''; ...
                    'ROBUSTNESS / PCA'; ...
                    '────────────────────────────────'; ...
                    sprintf('Norm/unnorm detection agreement: %.1f %%', ...
                    100*R.NormUnnormDetectionAgreement); ...
                    sprintf('Width direction agreement: %s', ...
                    app.passText(R.DirectionAgreement)); ...
                    sprintf('PCA domain: %s',R.PCADomain); ...
                    sprintf('Best PC: PC%d',R.BestPC); ...
                    sprintf('PCA loading level: %s',R.PCALoadingLevel); ...
                    sprintf('PCA support: %s', ...
                    app.passText(R.PCALoadingSupport)); ...
                    ''; ...
                    'CORE EVIDENCE'; ...
                    '────────────────────────────────'; ...
                    sprintf('1 Representation          : %s', ...
                    app.passText(R.EvidenceRepresentation)); ...
                    sprintf('2 Width effect            : %s', ...
                    app.passText(R.EvidenceEffect)); ...
                    sprintf('3 Width distribution      : %s', ...
                    app.passText(R.EvidenceDistribution)); ...
                    sprintf('4 Bootstrap + CI          : %s', ...
                    app.passText(R.EvidenceBootstrapCI)); ...
                    sprintf('5 Norm/unnorm robustness  : %s', ...
                    app.passText(R.EvidenceRobustness)); ...
                    ''; ...
                    sprintf('EVIDENCE SCORE: %d/5',R.Score); ...
                    sprintf('STATE: %s',R.Status); ...
                    sprintf('DECISION: %s',R.Decision) ...
                    };
                
                
            else
                
                lines = ...
                    { ...
                    'VALIDATION RESULT'; ...
                    ''; ...
                    sprintf('Unsupported validation mode: %s',mode) ...
                    };
                
            end
            
            
            app.setTextArea(lines);
            
        end


        function appendMorphologyCandidatesToTable(app,R)
            if isempty(R)
                return
            end
            
            if isempty(app.UITable.Data) || ~istable(app.UITable.Data)
                app.populateCandidateTable();
            end
            
            n = height(R);
            
            Candidate = string(R.Candidate);
            FeatureType = string(R.FeatureType);
            Mode = string(R.Mode);
            Domain = string(R.Domain);
            
            % Search window used later for individual-spectrum validation
            ROILow = R.SearchLow;
            ROIHigh = R.SearchHigh;
            
            RepresentativeWN = R.RepresentativeWN;
            
            Direction = strings(n,1);
            
            for k = 1:n
                if FeatureType(k) == "POSITION"
                    d = R.GroupBValue(k)-R.GroupAValue(k);
                    
                    if d > 0
                        Direction(k) = app.groupB + " higher WN";
                    elseif d < 0
                        Direction(k) = app.groupB + " lower WN";
                    else
                        Direction(k) = "No mean-position difference";
                    end
                    
                elseif FeatureType(k) == "FWHM"
                    d = R.GroupBValue(k)-R.GroupAValue(k);
                    
                    if d > 0
                        Direction(k) = app.groupB + " broader";
                    elseif d < 0
                        Direction(k) = app.groupB + " narrower";
                    else
                        Direction(k) = "No mean-FWHM difference";
                    end
                end
            end
            
            DiscoveryScore = NaN(n,1);
            Validation = repmat("NOT VALIDATED",n,1);
            Decision = repmat("PENDING",n,1);
            
            Tnew = table( ...
                Candidate,FeatureType,Mode,Domain, ...
                ROILow,ROIHigh,RepresentativeWN,Direction, ...
                DiscoveryScore,Validation,Decision);
            
            Told = app.UITable.Data;
            
            % Remove old morphology rows first, preventing duplicates if reopened
            if ismember('FeatureType',Told.Properties.VariableNames)
                keep = string(Told.FeatureType) == "INTENSITY";
                Told = Told(keep,:);
            end
            
            app.UITable.Data = [Told;Tnew];
        end


        function buildFinalSuggestedCandidateTable(app)
            % ================================================================
            % PRESERVE CURRENT REVIEW / VALIDATION STATE
            % ================================================================
            
            oldTable = table();
            
            if istable(app.UITable.Data) && ...
                    ~isempty(app.UITable.Data)
                
                oldTable = ...
                    app.UITable.Data;
            end
            
            % ================================================================
            % INTENSITY CANDIDATES
            % ================================================================
            
            if isempty(app.ROITable)
                
                Tint = table();
                
            else
                
                R = app.ROITable;
                n = height(R);
                
                Candidate = string(R.ROIID);
                FeatureType = repmat("INTENSITY",n,1);
                Mode = repmat("SHARED",n,1);
                DiscoveryTier = string(R.DiscoveryTier);
                Domain = string(R.ROIDomain);
                
                ROILow = R.RegionLow;
                ROIHigh = R.RegionHigh;
                RepresentativeWN = R.RepresentativeWN;
                
                Direction = string(R.Direction);
                DiscoveryScore = R.DiscoveryScore;
                
                Validation = repmat("SUGGESTED",n,1);
                Decision = repmat("PENDING",n,1);
                
                Tint = table( ...
                    Candidate,FeatureType,Mode,DiscoveryTier,Domain, ...
                    ROILow,ROIHigh,RepresentativeWN,Direction, ...
                    DiscoveryScore,Validation,Decision);
            end
            
            % ================================================================
            % MORPHOLOGY CANDIDATES
            % ================================================================
            
            if isempty(app.MorphologyCandidates)
                
                Tm = table();
                
            else
                
                R = app.MorphologyCandidates;
                n = height(R);
                
                Candidate = string(R.Candidate);
                FeatureType = string(R.FeatureType);
                Mode = string(R.Mode);
                
                DiscoveryTier = ...
                    repmat("HUMAN-SCREENED",n,1);
                
                isCustom = ...
                    startsWith( ...
                    Candidate, ...
                    "CUS_");
                
                DiscoveryTier(isCustom) = ...
                    "CUSTOM-USER";
                
                Domain = string(R.Domain);
                
                ROILow = R.SearchLow;
                ROIHigh = R.SearchHigh;
                RepresentativeWN = R.RepresentativeWN;
                
                Direction = strings(n,1);
                
                for k = 1:n
                    
                    if FeatureType(k) == "POSITION"
                        
                        d = R.GroupBValue(k)-R.GroupAValue(k);
                        
                        if d > 0
                            Direction(k) = app.groupB + " higher WN";
                        elseif d < 0
                            Direction(k) = app.groupB + " lower WN";
                        else
                            Direction(k) = "No mean-position difference";
                        end
                        
                    elseif FeatureType(k) == "FWHM"
                        
                        d = R.GroupBValue(k)-R.GroupAValue(k);
                        
                        if d > 0
                            Direction(k) = app.groupB + " broader";
                        elseif d < 0
                            Direction(k) = app.groupB + " narrower";
                        else
                            Direction(k) = "No mean-width difference";
                        end
                    end
                end
                
                DiscoveryScore = NaN(n,1);
                Validation = repmat("SUGGESTED",n,1);
                Decision = repmat("PENDING",n,1);
                
                Tm = table( ...
                    Candidate,FeatureType,Mode,DiscoveryTier,Domain, ...
                    ROILow,ROIHigh,RepresentativeWN,Direction, ...
                    DiscoveryScore,Validation,Decision);
            end
            
            % ================================================================
            % MERGE
            % ================================================================
            
            if isempty(Tint)
                T = Tm;
            elseif isempty(Tm)
                T = Tint;
            else
                T = [Tint;Tm];
            end
            % ================================================================
            % RESTORE EXISTING VALIDATION / DECISION
            %
            % Newly added candidates remain:
            %   Validation = SUGGESTED
            %   Decision   = PENDING
            %
            % Existing candidates keep their previous state.
            % ================================================================
            
            if ~isempty(oldTable) && ...
                    ismember('Candidate',oldTable.Properties.VariableNames) && ...
                    ismember('Validation',oldTable.Properties.VariableNames) && ...
                    ismember('Decision',oldTable.Properties.VariableNames)
                
                oldIDs = ...
                    string(oldTable.Candidate);
                
                newIDs = ...
                    string(T.Candidate);
                
                for k = 1:height(T)
                    
                    idxOld = ...
                        find( ...
                        oldIDs == newIDs(k), ...
                        1);
                    
                    if isempty(idxOld)
                        continue
                    end
                    
                    T.Validation(k) = ...
                        string(oldTable.Validation(idxOld));
                    
                    T.Decision(k) = ...
                        string(oldTable.Decision(idxOld));
                end
            end
            
            app.UITable.Data = T;
            
            app.UITable.ColumnName = { ...
                'Candidate', ...
                'Feature type', ...
                'Mode', ...
                'Discovery tier', ...
                'Domain', ...
                'ROI low', ...
                'ROI high', ...
                'Rep. WN', ...
                'Direction', ...
                'Discovery score', ...
                'Validation', ...
                'Decision'};
            
            app.UITable.ColumnEditable = false(1,12);
            
            app.UITable.ColumnFormat = { ...
                'char','char','char','char','char', ...
                'numeric','numeric','numeric','char', ...
                'numeric','char','char'};
            
            app.UITable.RowName = {};
        end


        function R = detectWidthPeakSet(app,X,searchMask,expectedWN)
            
            wn = double(app.wn(:));
            nSpec = size(X,2);
            
            Position = NaN(nSpec,1);
            FWHM = NaN(nSpec,1);
            Prominence = NaN(nSpec,1);
            ProminenceSNR = NaN(nSpec,1);
            Detected = false(nSpec,1);
            
            for k = 1:nSpec
                
                yFull = double(X(:,k));
                
                localMask = ...
                    searchMask & ...
                    wn >= expectedWN-app.Settings.Width.ShapeHalfWindow_cm1 & ...
                    wn <= expectedWN+app.Settings.Width.ShapeHalfWindow_cm1;
                
                idx = find(localMask);
                
                if numel(idx) < 7
                    continue
                end
                
                x = wn(idx);
                y = yFull(idx);
                
                if any(~isfinite(y))
                    continue
                end
                
                % Local linear baseline
                p = polyfit(x,y,1);
                residual = y-polyval(p,x);
                
                % Robust local noise
                d = diff(residual);
                dMed = median(d);
                sigmaNoise = ...
                    1.4826*median(abs(d-dMed))/sqrt(2);
                
                if ~isfinite(sigmaNoise) || sigmaNoise <= eps
                    sigmaNoise = std(d,0,'omitnan')/sqrt(2);
                end
                
                if ~isfinite(sigmaNoise) || sigmaNoise <= eps
                    sigmaNoise = eps;
                end
                
                minProminence = ...
                    app.Settings.Width.PrimarySNRThreshold*sigmaNoise;
                
                [pk,loc,width,prom] = findpeaks( ...
                    residual,x, ...
                    'MinPeakProminence',minProminence, ...
                    'MinPeakDistance', ...
                    app.Settings.Width.MinPeakDistance_cm1, ...
                    'WidthReference','halfheight');
                
                if isempty(loc)
                    continue
                end
                
                valid = ...
                    abs(loc-expectedWN) <= ...
                    app.Settings.Width.ShapeHalfWindow_cm1;
                
                if ~any(valid)
                    continue
                end
                
                validIdx = find(valid);
                
                [~,ii] = min(abs(loc(validIdx)-expectedWN));
                
                selected = validIdx(ii);
                
                Position(k) = loc(selected);
                FWHM(k) = width(selected);
                Prominence(k) = prom(selected);
                ProminenceSNR(k) = ...
                    prom(selected)/sigmaNoise;
                
                Detected(k) = ...
                    isfinite(FWHM(k)) && ...
                    FWHM(k) > 0;
            end
            
            R = struct();
            R.Position = Position;
            R.FWHM = FWHM;
            R.Prominence = Prominence;
            R.ProminenceSNR = ProminenceSNR;
            R.Detected = Detected;
        end


        function R = validateWidthCandidate(app,doPlot)
            
            if nargin < 2
                doPlot = true;
            end
            
            row = app.SelectedCandidateRow;
            T = app.UITable.Data;
            
            candidateID = string(T.Candidate(row));
            
            idxM = find( ...
                string(app.MorphologyCandidates.Candidate) == candidateID, ...
                1);
            
            if isempty(idxM)
                error('FWHM morphology metadata was not found.');
            end
            
            expectedA = ...
                app.MorphologyCandidates.ExpectedPositionA(idxM);
            
            expectedB = ...
                app.MorphologyCandidates.ExpectedPositionB(idxM);
            
            searchLow = ...
                app.MorphologyCandidates.SearchLow(idxM);
            
            searchHigh = ...
                app.MorphologyCandidates.SearchHigh(idxM);
            
            searchMask = ...
                app.wn >= searchLow & ...
                app.wn <= searchHigh;
            
            if nnz(searchMask) < 7
                error('FWHM search window contains too few points.');
            end
            
            searchWN = app.wn(searchMask);
            actualLow = min(searchWN);
            actualHigh = max(searchWN);
            
            gid = app.analysisGroupID;
            
            idxA = gid == app.groupA;
            idxB = gid == app.groupB;
            
            nA = nnz(idxA);
            nB = nnz(idxB);
            
            % ================================================================
            % INDIVIDUAL-SPECTRUM WIDTH DETECTION
            % ================================================================
            
            Aun = app.detectWidthPeakSet( ...
                app.X_unorm(:,idxA), ...
                searchMask,expectedA);
            
            Bun = app.detectWidthPeakSet( ...
                app.X_unorm(:,idxB), ...
                searchMask,expectedB);
            
            An = app.detectWidthPeakSet( ...
                app.X_norm(:,idxA), ...
                searchMask,expectedA);
            
            Bn = app.detectWidthPeakSet( ...
                app.X_norm(:,idxB), ...
                searchMask,expectedB);
            
            detectA = Aun.Detected;
            detectB = Bun.Detected;
            
            nDetectA = nnz(detectA);
            nDetectB = nnz(detectB);
            
            prevA = nDetectA/nA;
            prevB = nDetectB/nB;
            
            % ================================================================
            % E1 REPRESENTATION
            % ================================================================
            
            EvidenceRepresentation = ...
                prevA >= app.Settings.Width.MinPeakPrevalenceBothGroups && ...
                prevB >= app.Settings.Width.MinPeakPrevalenceBothGroups && ...
                nDetectA >= app.Settings.Width.MinDetectedPeaksPerGroup && ...
                nDetectB >= app.Settings.Width.MinDetectedPeaksPerGroup;
            
            widthA = Aun.FWHM(detectA);
            widthB = Bun.FWHM(detectB);
            
            widthA = widthA(isfinite(widthA) & widthA > 0);
            widthB = widthB(isfinite(widthB) & widthB > 0);
            
            if numel(widthA) < 3 || numel(widthB) < 3
                error( ...
                    'Too few valid individual-spectrum FWHM measurements.');
            end
            
            medianA = median(widthA);
            medianB = median(widthB);
            
            diffBminusA = medianB-medianA;
            
            % ================================================================
            % E2 WIDTH EFFECT
            % ================================================================
            
            widthEffect = ...
                app.robustEffectLocal(widthB,widthA);
            
            EvidenceEffect = ...
                isfinite(widthEffect) && ...
                abs(widthEffect) >= ...
                app.Settings.Width.MinRobustEffect;
            
            pWidth = app.safeRanksum(widthA,widthB);
            
            % ================================================================
            % E3 WIDTH DISPERSION
            % ================================================================
            
            relIQR_A = app.relativeIQRlocal(widthA);
            relIQR_B = app.relativeIQRlocal(widthB);
            
            EvidenceDistribution = ...
                isfinite(relIQR_A) && ...
                isfinite(relIQR_B) && ...
                relIQR_A <= app.Settings.Width.MaxRelativeIQR && ...
                relIQR_B <= app.Settings.Width.MaxRelativeIQR;
            
            % ================================================================
            % E4 BOOTSTRAP + CI
            % ================================================================
            
            seed = app.seedFromText( ...
                app.Settings.RandomSeed, ...
                "FWHM_"+candidateID);
            
            oldRng = rng;
            cleanup = onCleanup(@()rng(oldRng)); %#ok<NASGU>
            
            rng(seed,'twister');
            
            [bootWidth,bootStability,ciLow,ciHigh] = ...
                app.bootstrapShiftDifference( ...
                widthA,widthB, ...
                app.Settings.Width.NBootstrap);
            
            ciExcludesZero = ...
                ~(ciLow <= 0 && ciHigh >= 0);
            
            EvidenceBootstrap = ...
                bootStability >= ...
                app.Settings.Width.MinBootstrapDirectionStability && ...
                ciExcludesZero;
            
            % ================================================================
            % E5 NORMALIZED / UNNORMALIZED ROBUSTNESS
            % ================================================================
            
            agreementA = ...
                mean(Aun.Detected == An.Detected);
            
            agreementB = ...
                mean(Bun.Detected == Bn.Detected);
            
            overallAgreement = ...
                (nA*agreementA+nB*agreementB)/(nA+nB);
            
            widthAn = An.FWHM(An.Detected);
            widthBn = Bn.FWHM(Bn.Detected);
            
            normDirection = 0;
            
            if ~isempty(widthAn) && ~isempty(widthBn)
                normDirection = ...
                    sign(median(widthBn)-median(widthAn));
            end
            
            unnormDirection = ...
                sign(diffBminusA);
            
            directionAgreement = ...
                normDirection == unnormDirection && ...
                normDirection ~= 0;
            
            EvidenceRobustness = ...
                overallAgreement >= ...
                app.Settings.Width.MinNormUnnormDetectionAgreement && ...
                directionAgreement;
            
            % ================================================================
            % PCA = SUPPORT ONLY
            % ================================================================
            
            PInfo = app.evaluateShiftPCARegion( ...
                mean([expectedA expectedB]), ...
                actualLow,actualHigh);
            
            % ================================================================
            % SCORE
            % ================================================================
            
            ScoreNumeric = ...
                double(EvidenceRepresentation) + ...
                double(EvidenceEffect) + ...
                double(EvidenceDistribution) + ...
                double(EvidenceBootstrap) + ...
                double(EvidenceRobustness);
            
            switch ScoreNumeric
                case 5
                    status = "STRONG WIDTH EVIDENCE";
                case 4
                    status = "HIGH PRIORITY WIDTH EVIDENCE";
                case 3
                    status = "PROMISING WIDTH EVIDENCE";
                case 2
                    status = "REVIEW WIDTH EVIDENCE";
                otherwise
                    status = "WEAK WIDTH EVIDENCE";
            end
            
            if diffBminusA > 0
                direction = app.groupB + ...
                    " broader than " + app.groupA;
            elseif diffBminusA < 0
                direction = app.groupB + ...
                    " narrower than " + app.groupA;
            else
                direction = "No median width difference";
            end
            
            % ================================================================
            % RESULT
            % ================================================================
            
            R = struct();
            
            R.CandidateID = candidateID;
            R.Mode = "FWHM";
            R.ValidationTime = app.nowString();
            
            R.GroupA = app.groupA;
            R.GroupB = app.groupB;
            
            R.ExpectedA = expectedA;
            R.ExpectedB = expectedB;
            
            R.SearchLow = actualLow;
            R.SearchHigh = actualHigh;
            
            R.NA = nA;
            R.NB = nB;
            
            R.DetectedA = nDetectA;
            R.DetectedB = nDetectB;
            
            R.PrevalenceA = prevA;
            R.PrevalenceB = prevB;
            
            R.MedianWidthA = medianA;
            R.MedianWidthB = medianB;
            
            R.WidthDifference_B_minus_A = diffBminusA;
            R.Direction = direction;
            
            R.WidthEffect = widthEffect;
            R.RelativeIQR_A = relIQR_A;
            R.RelativeIQR_B = relIQR_B;
            
            R.WidthP = pWidth;
            
            R.Bootstrap = bootStability;
            R.CILow = ciLow;
            R.CIHigh = ciHigh;
            R.CIExcludesZero = ciExcludesZero;
            
            R.NormUnnormDetectionAgreement = overallAgreement;
            R.DirectionAgreement = directionAgreement;
            
            R.PCADomain = PInfo.Domain;
            R.BestPC = PInfo.BestPC;
            R.PCALoadingLevel = PInfo.LoadingLevel;
            R.PCALoadingSupport = PInfo.Support;
            R.PCAInfo = PInfo;
            
            R.EvidenceRepresentation = EvidenceRepresentation;
            R.EvidenceEffect = EvidenceEffect;
            R.EvidenceDistribution = EvidenceDistribution;
            R.EvidenceBootstrapCI = EvidenceBootstrap;
            R.EvidenceRobustness = EvidenceRobustness;
            
            R.Score = ScoreNumeric;
            R.Status = status;
            R.Decision = "PENDING";
            
            R.WidthA = widthA;
            R.WidthB = widthB;
            R.BootstrapWidth = bootWidth;
            R.SearchWN = searchWN;
            
            if doPlot
                app.plotWidthValidationFourAxes(R);
            end
        end


        function plotWidthValidationFourAxes(app,R)
            
            searchMask = ...
                app.wn >= R.SearchLow & ...
                app.wn <= R.SearchHigh;
            
            gid = app.analysisGroupID;
            
            idxA = gid == app.groupA;
            idxB = gid == app.groupB;
            
            x = app.wn(searchMask);
            
            medA = median( ...
                app.X_norm(searchMask,idxA), ...
                2,'omitnan');
            
            medB = median( ...
                app.X_norm(searchMask,idxB), ...
                2,'omitnan');
            
            % ================================================================
            % AXIS 1 - LOCAL MEDIAN MORPHOLOGY
            % ================================================================
            
            ax = app.UIAxes;
            
            cla(ax,'reset');
            hold(ax,'on');
            
            groupColors = lines(2);
            
            colorA = groupColors(1,:);
            colorB = groupColors(2,:);
            
            hA = plot( ...
                ax, ...
                x, ...
                medA, ...
                'Color',colorA, ...
                'LineWidth',1.8, ...
                'DisplayName',char(app.groupA));
            
            hB = plot( ...
                ax, ...
                x, ...
                medB, ...
                'Color',colorB, ...
                'LineWidth',1.8, ...
                'DisplayName',char(app.groupB));
            
            xline( ...
                ax, ...
                R.ExpectedA, ...
                '--', ...
                'Color',colorA, ...
                'LineWidth',1.2, ...
                'HandleVisibility','off');
            
            xline( ...
                ax, ...
                R.ExpectedB, ...
                '--', ...
                'Color',colorB, ...
                'LineWidth',1.2, ...
                'HandleVisibility','off');
            
            hold(ax,'off');
            
            xlabel(ax,'Raman shift (cm^{-1})');
            ylabel(ax,'Vector-normalized intensity');
            
            title( ...
                ax, ...
                sprintf( ...
                'Median morphology | %s %.2f | %s %.2f cm^{-1}', ...
                app.groupA, ...
                R.ExpectedA, ...
                app.groupB, ...
                R.ExpectedB), ...
                'Interpreter','none');
            
            legend( ...
                ax, ...
                [hA hB], ...
                {char(app.groupA),char(app.groupB)}, ...
                'Interpreter','none', ...
                'Location','best');
            
            box(ax,'on');
            
            % AXIS 2
            app.plotTwoGroupBox( ...
                app.UIAxes_2, ...
                R.WidthA,R.WidthB, ...
                app.groupA,app.groupB);
            
            ylabel(app.UIAxes_2,'FWHM (cm^{-1})');
            
            title(app.UIAxes_2,sprintf( ...
                'FWHM B-A = %+.3f | effect=%.3f | p=%.3g', ...
                R.WidthDifference_B_minus_A, ...
                R.WidthEffect,R.WidthP));
            
            % AXIS 3
            ax = app.UIAxes_3;
            
            cla(ax,'reset');
            
            histogram(ax,R.BootstrapWidth);
            
            hold(ax,'on');
            
            xline(ax,0,'--');
            xline(ax,R.CILow,':');
            xline(ax,R.CIHigh,':');
            xline(ax,R.WidthDifference_B_minus_A,'-.');
            
            hold(ax,'off');
            
            xlabel(ax,'Bootstrap FWHM difference B-A (cm^{-1})');
            ylabel(ax,'Count');
            
            title(ax,sprintf( ...
                'Bootstrap %.1f%% | CI %.2f to %.2f', ...
                100*R.Bootstrap,R.CILow,R.CIHigh));
            
            box(ax,'on');
            
            % AXIS 4 = PCA SUPPORT
            P = R.PCAInfo.Model;
            pc = R.PCAInfo.BestPC;
            
            ax = app.UIAxes_4;
            
            cla(ax,'reset');
            
            plot( ...
                ax,P.Wavenumber, ...
                P.Loadings(:,pc), ...
                'LineWidth',1.3);
            
            hold(ax,'on');
            
            yline(ax,0,'--');
            xline(ax,R.SearchLow,':');
            xline(ax,R.SearchHigh,':');
            xline(ax,mean([R.ExpectedA R.ExpectedB]),'--');
            
            hold(ax,'off');
            
            xlim(ax,[R.SearchLow R.SearchHigh]);
            
            xlabel(ax,'Raman shift (cm^{-1})');
            ylabel(ax,sprintf('PC%d loading',pc));
            
            title(ax,sprintf( ...
                'PCA support | %s | PASS=%d', ...
                R.PCALoadingLevel, ...
                R.PCALoadingSupport));
            
            box(ax,'on');
        end


        function R = validateCandidateRow(app,row,doPlot)
            
            if nargin < 3
                doPlot = false;
            end
            
            T = app.UITable.Data;
            
            if row < 1 || row > height(T)
                error('Candidate row index is invalid.');
            end
            
            % Set current row because existing validators use it
            app.SelectedCandidateRow = row;
            app.SelectedCandidateID = string(T.Candidate(row));
            
            featureType = ...
                upper(string(T.FeatureType(row)));
            
            switch featureType
                
                case "INTENSITY"
                    
                    app.SelectedCandidateType = "SHARED";
                    
                    R = app.validateSharedCandidate( ...
                        "AUTO",doPlot);
                    
                case "POSITION"
                    
                    app.SelectedCandidateType = "SHIFTED";
                    
                    R = app.validateShiftCandidate( ...
                        doPlot);
                    
                case "FWHM"
                    
                    app.SelectedCandidateType = "FWHM";
                    
                    R = app.validateWidthCandidate( ...
                        doPlot);
                    
                otherwise
                    
                    error( ...
                        'Unsupported feature type: %s', ...
                        featureType);
            end
        end


        function validateAllCandidates(app)
            
            if ~app.requireDiscovery()
                return
            end
            
            T = app.UITable.Data;
            n = height(T);
            
            if n == 0
                return
            end
            
            nOK = 0;
            nError = 0;
            
            % Start clean validation cache
            app.ValidationCache = struct( ...
                'CandidateID',{}, ...
                'Mode',{}, ...
                'Result',{});
            
            % Keep decisions untouched
            app.CurrentValidation = struct();
            app.HasCurrentValidation = false;
            
            for row = 1:n
                
                app.SelectedCandidateRow = row;
                app.SelectedCandidateID = ...
                    string(app.UITable.Data.Candidate(row));
                
                % Immediate visible progress
                app.UITable.Data.Validation(row) = ...
                    "VALIDATING...";
                
                drawnow;
                
                try
                    
                    % No plotting during batch validation
                    R = ...
                        app.validateCandidateRow( ...
                        row,false);
                    
                    if isempty(R)
                        error('Validator returned no result.');
                    end
                    
                    % ============================================================
                    % COMMIT RESULT
                    % ============================================================
                    
                    app.cacheValidationResult(R);
                    
                    app.UITable.Data.Validation(row) = ...
                        sprintf( ...
                        'VALIDATED | %d/5 | %s', ...
                        R.Score, ...
                        R.Status);
                    
                    % Validation NEVER makes review decision
                    if app.UITable.Data.Decision(row) == ""
                        app.UITable.Data.Decision(row) = ...
                            "PENDING";
                    end
                    
                    nOK = nOK+1;
                    
                catch MErow
                    
                    nError = nError+1;
                    
                    app.UITable.Data.Validation(row) = ...
                        "VALIDATION ERROR";
                    
                    fprintf( ...
                        2, ...
                        'Validation failed for %s:\n%s\n', ...
                        app.SelectedCandidateID, ...
                        getReport( ...
                        MErow, ...
                        'extended', ...
                        'hyperlinks','off'));
                end
                
                drawnow;
            end
            
            % No row should remain logically selected after batch
            app.SelectedCandidateRow = NaN;
            app.SelectedCandidateID = "";
            app.SelectedCandidateType = "";
            app.CurrentValidation = struct();
            app.HasCurrentValidation = false;
            
            if nOK > 0
                app.WorkflowStage = "VALIDATED";
            else
                app.WorkflowStage = "DISCOVERY_READY";
            end
            
            app.clearValidationAxes();
            
            app.setTextArea({ ...
                'BATCH VALIDATION COMPLETE'; ...
                '────────────────────────────────'; ...
                ''; ...
                sprintf('Total candidates : %d',n); ...
                sprintf('Validated        : %d',nOK); ...
                sprintf('Errors           : %d',nError); ...
                ''; ...
                'Candidate decisions remain PENDING.'; ...
                ''; ...
                'Click any validated candidate to inspect'; ...
                'its detailed evidence and four plots.'; ...
                ''; ...
                'Confirm / Reject remains a human review step.' ...
                });
        end


        function upsertCandidateResult(app,R)
            
            E = struct( ...
                'CandidateID',string(R.CandidateID), ...
                'Mode',string(R.Mode), ...
                'Decision',string(R.Decision), ...
                'ValidatedAt',string(R.ValidationTime), ...
                'Result',R);
            
            idx = [];
            
            for k = 1:numel(app.CandidateResults)
                
                if string(app.CandidateResults(k).CandidateID) == ...
                        string(E.CandidateID)
                    
                    idx = k;
                    break
                end
            end
            
            if isempty(idx)
                app.CandidateResults(end+1) = E;
            else
                app.CandidateResults(idx) = E;
            end
        end
        
        
        function saveCurrentCandidateEvidence(app,R)
            
            if isempty(R) || ~isstruct(R)
                return
            end
            
            if app.EvidenceRoot == ""
                return
            end
            
            if ~isfolder(app.EvidenceRoot)
                mkdir(app.EvidenceRoot);
            end
            
            candidateID = ...
                app.makeSafeFileName( ...
                string(R.CandidateID));
            
            modeName = ...
                app.makeSafeFileName( ...
                string(R.Mode));
            
            decisionName = ...
                app.makeSafeFileName( ...
                string(R.Decision));
            
            stamp = ...
                char( ...
                datetime( ...
                'now', ...
                'Format','yyyyMMdd_HHmmss'));
            
            candidateFolder = ...
                fullfile( ...
                app.EvidenceRoot, ...
                candidateID);
            
            if ~isfolder(candidateFolder)
                mkdir(candidateFolder);
            end
            
            % ================================================================
            % SAVE RESULT STRUCT
            % ================================================================
            
            evidenceFile = ...
                fullfile( ...
                candidateFolder, ...
                sprintf( ...
                '%s_%s_%s_%s.mat', ...
                candidateID, ...
                modeName, ...
                decisionName, ...
                stamp));
            
            Evidence = struct();
            
            Evidence.CandidateID = ...
                string(R.CandidateID);
            
            Evidence.Mode = ...
                string(R.Mode);
            
            Evidence.Decision = ...
                string(R.Decision);
            
            Evidence.ValidationTime = ...
                string(R.ValidationTime);
            
            Evidence.SavedAt = ...
                string( ...
                datetime( ...
                'now', ...
                'Format','yyyy-MM-dd HH:mm:ss'));
            
            Evidence.Result = ...
                R;
            
            Evidence.SettingsVersion = ...
                string(app.Settings.Version);
            
            Evidence.GroupA = ...
                app.groupA;
            
            Evidence.GroupB = ...
                app.groupB;
            
            save( ...
                evidenceFile, ...
                'Evidence');
            
            % ================================================================
            % SAVE CURRENT FOUR-AXIS FIGURE
            % ================================================================
            
            try
                
                fig = ...
                    figure( ...
                    'Visible','off', ...
                    'Color','w', ...
                    'Position',[100 100 1400 900]);
                
                t = ...
                    tiledlayout( ...
                    fig, ...
                    2,2, ...
                    'Padding','compact', ...
                    'TileSpacing','compact');
                
                sourceAxes = { ...
                    app.UIAxes, ...
                    app.UIAxes_2, ...
                    app.UIAxes_3, ...
                    app.UIAxes_4};
                
                for k = 1:4
                    
                    dst = ...
                        nexttile(t);
                    
                    copyobj( ...
                        allchild(sourceAxes{k}), ...
                        dst);
                    
                    dst.XLim = ...
                        sourceAxes{k}.XLim;
                    
                    dst.YLim = ...
                        sourceAxes{k}.YLim;
                    
                    dst.XScale = ...
                        sourceAxes{k}.XScale;
                    
                    dst.YScale = ...
                        sourceAxes{k}.YScale;
                    
                    dst.Box = ...
                        sourceAxes{k}.Box;
                    
                    title( ...
                        dst, ...
                        sourceAxes{k}.Title.String, ...
                        'Interpreter','none');
                    
                    xlabel( ...
                        dst, ...
                        sourceAxes{k}.XLabel.String, ...
                        'Interpreter','none');
                    
                    ylabel( ...
                        dst, ...
                        sourceAxes{k}.YLabel.String, ...
                        'Interpreter','none');
                end
                
                figureFile = ...
                    fullfile( ...
                    candidateFolder, ...
                    sprintf( ...
                    '%s_%s_%s_%s.png', ...
                    candidateID, ...
                    modeName, ...
                    decisionName, ...
                    stamp));
                
                exportgraphics( ...
                    fig, ...
                    figureFile, ...
                    'Resolution', ...
                    app.Settings.Output.FigureResolution);
                
                close(fig);
                
            catch ME
                
                warning( ...
                    'Could not save evidence figure for %s: %s', ...
                    R.CandidateID, ...
                    ME.message);
            end
            
        end


        function saveAnalysisCheckpoint(app)
            
            % ================================================================
            % SAVE REPRODUCIBLE ANALYSIS CHECKPOINT
            % ================================================================
            
            if app.OutputRoot == ""
                error('OutputRoot is not defined.');
            end
            
            if ~isfolder(app.OutputRoot)
                mkdir(app.OutputRoot);
            end
            
            if app.AnalysisCheckpointPath == ""
                app.AnalysisCheckpointPath = string( ...
                    fullfile( ...
                    app.OutputRoot, ...
                    app.Settings.Output.AnalysisCheckpointName));
            end
            
            % ================================================================
            % BUILD CHECKPOINT STRUCT
            %
            % Important:
            % Save analysis state and derived results.
            % Do NOT duplicate large source matrices unnecessarily.
            % ================================================================
            
            C = struct();
            
            % ------------------------------------------------
            % VERSION / TIME
            % ------------------------------------------------
            
            C.Version = string(app.Settings.Version);
            
            C.SavedAt = string( ...
                datetime( ...
                'now', ...
                'Format','yyyy-MM-dd HH:mm:ss'));
            
            % ------------------------------------------------
            % SOURCE / PROVENANCE
            % ------------------------------------------------
            
            C.SessionFile = app.SessionFile;
            
            C.GroupA = app.groupA;
            C.GroupB = app.groupB;
            
            C.AnalysisGroupID = app.analysisGroupID;
            C.AnalysisGroupNames = app.analysisGroupNames;
            
            % ------------------------------------------------
            % SETTINGS
            % ------------------------------------------------
            
            C.Settings = app.Settings;
            
            % ------------------------------------------------
            % DISCOVERY
            % ------------------------------------------------
            
            C.ROITable = app.ROITable;
            C.MorphologyCandidates = app.MorphologyCandidates;
            
            % ------------------------------------------------
            % PCA
            % ------------------------------------------------
            
            C.PCA_Full = app.PCA_Full;
            C.PCA_Fingerprint = app.PCA_Fingerprint;
            C.PCA_HighWN = app.PCA_HighWN;
            
            % ------------------------------------------------
            % VALIDATION / REVIEW
            % ------------------------------------------------
            
            C.ValidationCache = app.ValidationCache;
            C.CandidateResults = app.CandidateResults;
            
            % ------------------------------------------------
            % FINAL TABLE
            % ------------------------------------------------
            
            if istable(app.UITable.Data)
                C.CandidateTable = app.UITable.Data;
            else
                C.CandidateTable = table();
            end
            
            % ------------------------------------------------
            % WORKFLOW STATE
            % ------------------------------------------------
            
            C.WorkflowStage = app.WorkflowStage;
            
            C.SelectedCandidateRow = app.SelectedCandidateRow;
            C.SelectedCandidateID = app.SelectedCandidateID;
            C.SelectedCandidateType = app.SelectedCandidateType;
            
            % ------------------------------------------------
            % REPRODUCIBILITY METADATA
            % ------------------------------------------------
            
            C.OutputRoot = app.OutputRoot;
            C.EvidenceRoot = app.EvidenceRoot;
            C.ExcelPath = app.ExcelPath;
            
            % ================================================================
            % SAVE
            %
            % Use -v7.3 because PCA/cache structures can become large.
            % ================================================================
            
            save( ...
                app.AnalysisCheckpointPath, ...
                'C', ...
                '-v7.3');
            
        end


        function exportAnalysisWorkbook(app)
            
            % ================================================================
            % EXPORT CURRENT ANALYSIS AUDIT TO EXCEL
            % ================================================================
            
            if app.OutputRoot == ""
                error('OutputRoot is not defined.');
            end
            
            if app.ExcelPath == ""
                app.ExcelPath = string( ...
                    fullfile( ...
                    app.OutputRoot, ...
                    app.Settings.Output.ExcelName));
            end
            
            % ================================================================
            % SHEET 1 - CANDIDATES
            % ================================================================
            
            if istable(app.UITable.Data)
                candidateTable = app.UITable.Data;
            else
                candidateTable = table();
            end
            
            writetable( ...
                candidateTable, ...
                app.ExcelPath, ...
                'Sheet','Candidates');
            
            % ================================================================
            % SHEET 2 - REVIEW SUMMARY
            % ================================================================
            
            if isempty(app.CandidateResults)
                
                reviewTable = table();
                
            else
                
                n = numel(app.CandidateResults);
                
                CandidateID = strings(n,1);
                Mode = strings(n,1);
                Decision = strings(n,1);
                ValidatedAt = strings(n,1);
                Score = NaN(n,1);
                Status = strings(n,1);
                
                for k = 1:n
                    
                    E = app.CandidateResults(k);
                    
                    CandidateID(k) = string(E.CandidateID);
                    Mode(k) = string(E.Mode);
                    Decision(k) = string(E.Decision);
                    ValidatedAt(k) = string(E.ValidatedAt);
                    
                    if isfield(E,'Result') && isstruct(E.Result)
                        
                        if isfield(E.Result,'Score')
                            Score(k) = double(E.Result.Score);
                        end
                        
                        if isfield(E.Result,'Status')
                            Status(k) = string(E.Result.Status);
                        end
                    end
                end
                
                reviewTable = table( ...
                    CandidateID, ...
                    Mode, ...
                    Decision, ...
                    ValidatedAt, ...
                    Score, ...
                    Status);
            end
            
            writetable( ...
                reviewTable, ...
                app.ExcelPath, ...
                'Sheet','ReviewSummary');
            
            % ================================================================
            % SHEET 3 - SETTINGS
            % ================================================================
            
            settingName = strings(0,1);
            settingValue = strings(0,1);
            
            % General
            settingName(end+1,1) = "Version";
            settingValue(end+1,1) = string(app.Settings.Version);
            
            settingName(end+1,1) = "Normalization";
            settingValue(end+1,1) = string(app.Settings.Normalization.Method);
            
            settingName(end+1,1) = "AnalysisRange";
            settingValue(end+1,1) = sprintf( ...
                '%.0f-%.0f', ...
                app.Settings.Range.Analysis);
            
            % Discovery
            settingName(end+1,1) = "DiscoveryDifferencePercentile";
            settingValue(end+1,1) = string( ...
                app.Settings.Discovery.DifferencePercentile);
            
            settingName(end+1,1) = "DiscoveryRobustEffectThreshold";
            settingValue(end+1,1) = string( ...
                app.Settings.Discovery.RobustEffectThreshold);
            
            % Shared
            settingName(end+1,1) = "SharedMinEffectNorm";
            settingValue(end+1,1) = string( ...
                app.Settings.Shared.MinEffectNorm);
            
            settingName(end+1,1) = "SharedMaxRelativeIQR";
            settingValue(end+1,1) = string( ...
                app.Settings.Shared.MaxRelativeIQR);
            
            settingName(end+1,1) = "SharedBootstrapStability";
            settingValue(end+1,1) = string( ...
                app.Settings.Shared.MinBootstrapDirectionStability);
            
            % Shift
            settingName(end+1,1) = "ShiftMinAbsoluteShift_cm1";
            settingValue(end+1,1) = string( ...
                app.Settings.Shift.MinAbsoluteShift_cm1);
            
            settingName(end+1,1) = "ShiftMaxPositionIQR_cm1";
            settingValue(end+1,1) = string( ...
                app.Settings.Shift.MaxPositionIQR_cm1);
            
            settingName(end+1,1) = "ShiftBootstrapStability";
            settingValue(end+1,1) = string( ...
                app.Settings.Shift.MinBootstrapDirectionStability);
            
            % Width
            settingName(end+1,1) = "WidthMinRobustEffect";
            settingValue(end+1,1) = string( ...
                app.Settings.Width.MinRobustEffect);
            
            settingName(end+1,1) = "WidthMaxRelativeIQR";
            settingValue(end+1,1) = string( ...
                app.Settings.Width.MaxRelativeIQR);
            
            settingName(end+1,1) = "WidthBootstrapStability";
            settingValue(end+1,1) = string( ...
                app.Settings.Width.MinBootstrapDirectionStability);
            
            settingsTable = table( ...
                settingName, ...
                settingValue, ...
                'VariableNames', ...
                {'Setting','Value'});
            
            writetable( ...
                settingsTable, ...
                app.ExcelPath, ...
                'Sheet','Settings');
            
        end


        function domain = domainFromCustomWN(app,wnValue)
            
            if ~isfinite(wnValue)
                
                error( ...
                    'Candidate wavenumber must be numeric.');
                
            elseif wnValue >= ...
                    app.Settings.Range.Fingerprint(1) && ...
                    wnValue <= ...
                    app.Settings.Range.Fingerprint(2)
                
                domain = ...
                    "Fingerprint";
                
            elseif wnValue >= ...
                    app.Settings.Range.HighWN(1) && ...
                    wnValue <= ...
                    app.Settings.Range.HighWN(2)
                
                domain = ...
                    "High-WN";
                
            else
                
                error( ...
                    [ ...
                    'Custom candidates are restricted to the analysis domains:' ...
                    newline ...
                    'Fingerprint: %.0f-%.0f cm^-1' ...
                    newline ...
                    'High-WN: %.0f-%.0f cm^-1' ...
                    ], ...
                    app.Settings.Range.Fingerprint, ...
                    app.Settings.Range.HighWN);
            end
            
        end


        function candidateID = makeCustomCandidateID( ...
                app,prefix,representativeWN)
            
            prefix = ...
                upper(string(prefix));
            
            base = ...
                sprintf( ...
                'CUS_%s_%.1f', ...
                prefix, ...
                representativeWN);
            
            existing = ...
                strings(0,1);
            
            % ================================================================
            % CURRENT FINAL TABLE
            % ================================================================
            
            if istable(app.UITable.Data) && ...
                    ~isempty(app.UITable.Data) && ...
                    ismember( ...
                    'Candidate', ...
                    app.UITable.Data.Properties.VariableNames)
                
                existing = ...
                    [ ...
                    existing; ...
                    string(app.UITable.Data.Candidate) ...
                    ];
            end
            
            % ================================================================
            % INTENSITY BACKING TABLE
            % ================================================================
            
            if istable(app.ROITable) && ...
                    ~isempty(app.ROITable) && ...
                    ismember( ...
                    'ROIID', ...
                    app.ROITable.Properties.VariableNames)
                
                existing = ...
                    [ ...
                    existing; ...
                    string(app.ROITable.ROIID) ...
                    ];
            end
            
            % ================================================================
            % MORPHOLOGY BACKING TABLE
            % ================================================================
            
            if istable(app.MorphologyCandidates) && ...
                    ~isempty(app.MorphologyCandidates) && ...
                    ismember( ...
                    'Candidate', ...
                    app.MorphologyCandidates.Properties.VariableNames)
                
                existing = ...
                    [ ...
                    existing; ...
                    string(app.MorphologyCandidates.Candidate) ...
                    ];
            end
            
            candidateID = ...
                string(base);
            
            counter = ...
                1;
            
            while any(existing == candidateID)
                
                counter = ...
                    counter + 1;
                
                candidateID = ...
                    string( ...
                    sprintf( ...
                    '%s_%02d', ...
                    base, ...
                    counter));
            end
            
        end


        function addCustomIntensityCandidate(app)
            
            % ================================================================
            % CUSTOM INTENSITY
            %
            % User defines:
            % 1 representative WN
            % 2 ROI low
            % 3 ROI high
            %
            % Validation afterward is IDENTICAL to SHARED validation.
            % ================================================================
            
            answer = inputdlg( ...
                { ...
                'Representative WN (cm^-1):', ...
                'ROI low (cm^-1):', ...
                'ROI high (cm^-1):' ...
                }, ...
                'Custom INTENSITY candidate', ...
                [1 52], ...
                { ...
                '1004', ...
                '995', ...
                '1013' ...
                });
            
            if isempty(answer)
                return
            end
            
            repWN = ...
                str2double(answer{1});
            
            roiLow = ...
                str2double(answer{2});
            
            roiHigh = ...
                str2double(answer{3});
            
            values = ...
                [repWN roiLow roiHigh];
            
            if any(~isfinite(values))
                
                error( ...
                    'Representative WN and ROI limits must be numeric.');
            end
            
            if roiLow >= roiHigh
                
                error( ...
                    'ROI low must be smaller than ROI high.');
            end
            
            if repWN < roiLow || ...
                    repWN > roiHigh
                
                error( ...
                    'Representative WN must lie inside the ROI.');
            end
            
            domain = ...
                app.domainFromCustomWN(repWN);
            
            % ROI must remain inside SAME Raman domain
            if domain == "Fingerprint"
                
                if roiLow < ...
                        app.Settings.Range.Fingerprint(1) || ...
                        roiHigh > ...
                        app.Settings.Range.Fingerprint(2)
                    
                    error( ...
                        'Fingerprint custom ROI must remain within %.0f-%.0f cm^-1.', ...
                        app.Settings.Range.Fingerprint);
                end
                
            else
                
                if roiLow < ...
                        app.Settings.Range.HighWN(1) || ...
                        roiHigh > ...
                        app.Settings.Range.HighWN(2)
                    
                    error( ...
                        'High-WN custom ROI must remain within %.0f-%.0f cm^-1.', ...
                        app.Settings.Range.HighWN);
                end
            end
            
            % ================================================================
            % MAP TO ACTUAL SPECTRAL GRID
            % ================================================================
            
            [~,idxRep] = ...
                min(abs(app.wn-repWN));
            
            actualWN = ...
                app.wn(idxRep);
            
            roiMask = ...
                app.wn >= roiLow & ...
                app.wn <= roiHigh;
            
            if nnz(roiMask) < 2
                
                error( ...
                    'Custom intensity ROI contains fewer than two spectral points.');
            end
            
            actualLow = ...
                min(app.wn(roiMask));
            
            actualHigh = ...
                max(app.wn(roiMask));
            
            candidateID = ...
                app.makeCustomCandidateID( ...
                "INT",actualWN);
            
            % ================================================================
            % DETERMINE PRIMARY MEASUREMENT THE SAME WAY AS DISCOVERY
            % ================================================================
            
            regionWidth = ...
                actualHigh-actualLow;
            
            if regionWidth <= ...
                    app.Settings.Discovery.SharpFeatureWidth_cm1
                
                suggestedMeasure = ...
                    "Peak + local area";
                
            else
                
                suggestedMeasure = ...
                    "Area preferred";
            end
            
            % ================================================================
            % PREVIEW DIRECTION
            %
            % This is descriptive only.
            % Validation later recalculates actual evidence.
            % ================================================================
            
            gid = ...
                app.analysisGroupID;
            
            idxA = ...
                gid == app.groupA;
            
            idxB = ...
                gid == app.groupB;
            
            peakValues = ...
                app.X_norm(idxRep,:)';
            
            previewEffect = ...
                app.robustEffectLocal( ...
                peakValues(idxA), ...
                peakValues(idxB));
            
            if previewEffect > 0
                
                direction = ...
                    app.groupA + ...
                    " > " + ...
                    app.groupB;
                
            elseif previewEffect < 0
                
                direction = ...
                    app.groupB + ...
                    " > " + ...
                    app.groupA;
                
            else
                
                direction = ...
                    "No preview direction";
            end
            
            % ================================================================
            % CREATE ROW WITH EXACT ROITable SCHEMA
            % ================================================================
            
            ROIID = ...
                candidateID;
            
            ROIDomain = ...
                domain;
            
            RegionLow = ...
                actualLow;
            
            RegionHigh = ...
                actualHigh;
            
            RegionWidth = ...
                regionWidth;
            
            RepresentativeWN = ...
                actualWN;
            
            Direction = ...
                direction;
            
            MaxAbsMedianDifferenceNorm = ...
                NaN;
            
            MaxAbsRobustEffectNorm = ...
                NaN;
            
            RepresentativeRobustEffectNorm = ...
                previewEffect;
            
            RepresentativeRobustEffectUnnorm = ...
                NaN;
            
            NormalizationDirectionAgreement = ...
                false;
            
            PeakRobustEffect_Norm = ...
                previewEffect;
            
            AreaRobustEffect_Norm = ...
                NaN;
            
            SuggestedMeasure = ...
                suggestedMeasure;
            
            DiscoveryTier = ...
                "CUSTOM-USER";
            
            DiscoveryScore = ...
                NaN;
            
            Tnew = table( ...
                ROIID, ...
                ROIDomain, ...
                RegionLow, ...
                RegionHigh, ...
                RegionWidth, ...
                RepresentativeWN, ...
                Direction, ...
                MaxAbsMedianDifferenceNorm, ...
                MaxAbsRobustEffectNorm, ...
                RepresentativeRobustEffectNorm, ...
                RepresentativeRobustEffectUnnorm, ...
                NormalizationDirectionAgreement, ...
                PeakRobustEffect_Norm, ...
                AreaRobustEffect_Norm, ...
                SuggestedMeasure, ...
                DiscoveryTier, ...
                DiscoveryScore);
            
            % ================================================================
            % APPEND TO BACKING TABLE
            % ================================================================
            
            if isempty(app.ROITable)
                
                app.ROITable = ...
                    Tnew;
                
            else
                
                app.ROITable = ...
                    [app.ROITable;Tnew];
            end
            
            % ================================================================
            % REBUILD FINAL TABLE
            % ================================================================
            
            app.buildFinalSuggestedCandidateTable();
            
            app.WorkflowStage = ...
                "DISCOVERY_READY";
            
            app.setTextArea({ ...
                'CUSTOM CANDIDATE ADDED'; ...
                '────────────────────────────────'; ...
                ''; ...
                sprintf('Candidate: %s',candidateID); ...
                'Feature type: INTENSITY'; ...
                'Validation mode: SHARED'; ...
                sprintf('Domain: %s',domain); ...
                sprintf('Representative WN: %.3f cm^-1',actualWN); ...
                sprintf('ROI: %.3f - %.3f cm^-1',actualLow,actualHigh); ...
                sprintf('Suggested measure: %s',suggestedMeasure); ...
                sprintf('Preview trend: %s',direction); ...
                ''; ...
                'Source: USER CUSTOM'; ...
                ''; ...
                'This is a suggested candidate only.'; ...
                'It will undergo the same SHARED validation as automatic candidates.' ...
                });
            
        end


        function addCustomPositionCandidate(app)
            
            % ================================================================
            % CUSTOM POSITION / SHIFT
            %
            % IMPORTANT:
            % User must identify the corresponding mean-band position
            % separately for Group A and Group B.
            %
            % This mirrors check_shift morphology screening.
            % ================================================================
            
            answer = inputdlg( ...
                { ...
                sprintf('Expected peak position in %s (cm^-1):',app.groupA), ...
                sprintf('Expected peak position in %s (cm^-1):',app.groupB) ...
                }, ...
                'Custom POSITION / SHIFT candidate', ...
                [1 58], ...
                { ...
                '1004', ...
                '1007' ...
                });
            
            if isempty(answer)
                return
            end
            
            expectedA = ...
                str2double(answer{1});
            
            expectedB = ...
                str2double(answer{2});
            
            if any(~isfinite([expectedA expectedB]))
                
                error( ...
                    'Expected peak positions for both groups must be numeric.');
            end
            
            representativeWN = ...
                mean([expectedA expectedB]);
            
            domain = ...
                app.domainFromCustomWN( ...
                representativeWN);
            
            % ================================================================
            % BOTH EXPECTED PEAKS MUST BE IN SAME RAMAN DOMAIN
            % ================================================================
            
            domainA = ...
                app.domainFromCustomWN(expectedA);
            
            domainB = ...
                app.domainFromCustomWN(expectedB);
            
            if domainA ~= domainB
                
                error( ...
                    [ ...
                    'Expected Group A and Group B peaks must belong ' ...
                    'to the same Raman domain.' ...
                    ]);
            end
            
            % ================================================================
            % EXACT SAME SEARCH-WINDOW LOGIC AS check_shift
            % ================================================================
            
            searchLow = ...
                min(expectedA,expectedB)-25;
            
            searchHigh = ...
                max(expectedA,expectedB)+25;
            
            % Clip ONLY to domain boundary
            if domain == "Fingerprint"
                
                searchLow = ...
                    max( ...
                    searchLow, ...
                    app.Settings.Range.Fingerprint(1));
                
                searchHigh = ...
                    min( ...
                    searchHigh, ...
                    app.Settings.Range.Fingerprint(2));
                
            else
                
                searchLow = ...
                    max( ...
                    searchLow, ...
                    app.Settings.Range.HighWN(1));
                
                searchHigh = ...
                    min( ...
                    searchHigh, ...
                    app.Settings.Range.HighWN(2));
            end
            
            candidateID = ...
                app.makeCustomCandidateID( ...
                "POS",representativeWN);
            
            % ================================================================
            % BUILD EXACT MORPHOLOGY TABLE SCHEMA
            % ================================================================
            
            Candidate = ...
                candidateID;
            
            FeatureType = ...
                "POSITION";
            
            Mode = ...
                "SHIFTED";
            
            Domain = ...
                domain;
            
            RepresentativeWN = ...
                representativeWN;
            
            GroupAValue = ...
                expectedA;
            
            GroupBValue = ...
                expectedB;
            
            ExpectedPositionA = ...
                expectedA;
            
            ExpectedPositionB = ...
                expectedB;
            
            % Not manually measured here
            FWHM_A = ...
                NaN;
            
            FWHM_B = ...
                NaN;
            
            ProminenceA = ...
                NaN;
            
            ProminenceB = ...
                NaN;
            
            SearchLow = ...
                searchLow;
            
            SearchHigh = ...
                searchHigh;
            
            Tnew = table();
            
            Tnew.Candidate = ...
                Candidate;
            
            Tnew.FeatureType = ...
                FeatureType;
            
            Tnew.Mode = ...
                Mode;
            
            Tnew.Domain = ...
                Domain;
            
            Tnew.RepresentativeWN = ...
                RepresentativeWN;
            
            Tnew.GroupAValue = ...
                GroupAValue;
            
            Tnew.GroupBValue = ...
                GroupBValue;
            
            Tnew.ExpectedPositionA = ...
                ExpectedPositionA;
            
            Tnew.ExpectedPositionB = ...
                ExpectedPositionB;
            
            Tnew.FWHM_A = ...
                FWHM_A;
            
            Tnew.FWHM_B = ...
                FWHM_B;
            
            Tnew.ProminenceA = ...
                ProminenceA;
            
            Tnew.ProminenceB = ...
                ProminenceB;
            
            Tnew.SearchLow = ...
                SearchLow;
            
            Tnew.SearchHigh = ...
                SearchHigh;
            
            % ================================================================
            % APPEND
            % ================================================================
            
            if isempty(app.MorphologyCandidates)
                
                app.MorphologyCandidates = ...
                    Tnew;
                
            else
                
                app.MorphologyCandidates = ...
                    [app.MorphologyCandidates;Tnew];
            end
            
            app.buildFinalSuggestedCandidateTable();
            
            app.WorkflowStage = ...
                "DISCOVERY_READY";
            
            shiftPreview = ...
                expectedB-expectedA;
            
            app.setTextArea({ ...
                'CUSTOM CANDIDATE ADDED'; ...
                '────────────────────────────────'; ...
                ''; ...
                sprintf('Candidate: %s',candidateID); ...
                'Feature type: POSITION'; ...
                'Validation mode: SHIFTED'; ...
                sprintf('Domain: %s',domain); ...
                ''; ...
                sprintf('%s expected peak: %.3f cm^-1', ...
                app.groupA,expectedA); ...
                sprintf('%s expected peak: %.3f cm^-1', ...
                app.groupB,expectedB); ...
                sprintf('Screening B-A shift: %+.3f cm^-1', ...
                shiftPreview); ...
                sprintf('Representative WN: %.3f cm^-1', ...
                representativeWN); ...
                sprintf('Validation search: %.3f - %.3f cm^-1', ...
                searchLow,searchHigh); ...
                ''; ...
                'Source: USER CUSTOM'; ...
                ''; ...
                'Expected positions are screening anchors only.'; ...
                'Final positions will be detected independently from individual spectra.' ...
                });
            
        end

        function addCustomFWHMCandidate(app)
            
            answer = inputdlg( ...
                { ...
                sprintf('Expected peak position in %s (cm^-1):',app.groupA), ...
                sprintf('Expected peak position in %s (cm^-1):',app.groupB), ...
                sprintf('Observed screening FWHM in %s (cm^-1):',app.groupA), ...
                sprintf('Observed screening FWHM in %s (cm^-1):',app.groupB) ...
                }, ...
                'Custom FWHM candidate', ...
                [1 60], ...
                { ...
                '1004', ...
                '1004', ...
                '12', ...
                '16' ...
                });
            
            if isempty(answer)
                return
            end
            
            expectedA = ...
                str2double(answer{1});
            
            expectedB = ...
                str2double(answer{2});
            
            fwhmA = ...
                str2double(answer{3});
            
            fwhmB = ...
                str2double(answer{4});
            
            values = ...
                [expectedA expectedB fwhmA fwhmB];
            
            if any(~isfinite(values))
                
                error( ...
                    'All FWHM candidate values must be numeric.');
            end
            
            if fwhmA <= 0 || ...
                    fwhmB <= 0
                
                error( ...
                    'Screening FWHM values must be greater than zero.');
            end
            
            representativeWN = ...
                mean([expectedA expectedB]);
            
            domainA = ...
                app.domainFromCustomWN(expectedA);
            
            domainB = ...
                app.domainFromCustomWN(expectedB);
            
            if domainA ~= domainB
                
                error( ...
                    [ ...
                    'Expected Group A and Group B peaks must belong ' ...
                    'to the same Raman domain.' ...
                    ]);
            end
            
            domain = ...
                domainA;
            
            % Same morphology search convention
            searchLow = ...
                min(expectedA,expectedB)-25;
            
            searchHigh = ...
                max(expectedA,expectedB)+25;
            
            if domain == "Fingerprint"
                
                searchLow = ...
                    max( ...
                    searchLow, ...
                    app.Settings.Range.Fingerprint(1));
                
                searchHigh = ...
                    min( ...
                    searchHigh, ...
                    app.Settings.Range.Fingerprint(2));
                
            else
                
                searchLow = ...
                    max( ...
                    searchLow, ...
                    app.Settings.Range.HighWN(1));
                
                searchHigh = ...
                    min( ...
                    searchHigh, ...
                    app.Settings.Range.HighWN(2));
            end
            
            candidateID = ...
                app.makeCustomCandidateID( ...
                "FWHM",representativeWN);
            
            % ================================================================
            % EXACT MORPHOLOGY BACKING SCHEMA
            % ================================================================
            
            Tnew = ...
                table();
            
            Tnew.Candidate = ...
                candidateID;
            
            Tnew.FeatureType = ...
                "FWHM";
            
            Tnew.Mode = ...
                "FWHM";
            
            Tnew.Domain = ...
                domain;
            
            Tnew.RepresentativeWN = ...
                representativeWN;
            
            Tnew.GroupAValue = ...
                fwhmA;
            
            Tnew.GroupBValue = ...
                fwhmB;
            
            Tnew.ExpectedPositionA = ...
                expectedA;
            
            Tnew.ExpectedPositionB = ...
                expectedB;
            
            Tnew.FWHM_A = ...
                fwhmA;
            
            Tnew.FWHM_B = ...
                fwhmB;
            
            Tnew.ProminenceA = ...
                NaN;
            
            Tnew.ProminenceB = ...
                NaN;
            
            Tnew.SearchLow = ...
                searchLow;
            
            Tnew.SearchHigh = ...
                searchHigh;
            
            if isempty(app.MorphologyCandidates)
                
                app.MorphologyCandidates = ...
                    Tnew;
                
            else
                
                app.MorphologyCandidates = ...
                    [app.MorphologyCandidates;Tnew];
            end
            
            app.buildFinalSuggestedCandidateTable();
            
            app.WorkflowStage = ...
                "DISCOVERY_READY";
            
            app.setTextArea({ ...
                'CUSTOM CANDIDATE ADDED'; ...
                '────────────────────────────────'; ...
                ''; ...
                sprintf('Candidate: %s',candidateID); ...
                'Feature type: FWHM'; ...
                'Validation mode: FWHM'; ...
                sprintf('Domain: %s',domain); ...
                ''; ...
                sprintf('%s expected peak: %.3f cm^-1', ...
                app.groupA,expectedA); ...
                sprintf('%s expected peak: %.3f cm^-1', ...
                app.groupB,expectedB); ...
                sprintf('%s screening FWHM: %.3f cm^-1', ...
                app.groupA,fwhmA); ...
                sprintf('%s screening FWHM: %.3f cm^-1', ...
                app.groupB,fwhmB); ...
                sprintf('Representative WN: %.3f cm^-1', ...
                representativeWN); ...
                sprintf('Validation search: %.3f - %.3f cm^-1', ...
                searchLow,searchHigh); ...
                ''; ...
                'Source: USER CUSTOM'; ...
                ''; ...
                'Entered FWHM values are morphology-screening observations only.'; ...
                'Validation will re-estimate FWHM from individual spectra.' ...
                });
            
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % ================================================================
            % RAMAN CANDIDATE CONFIRMATION APP
            % STARTUP
            % ================================================================
            
            app.UIFigure.Name = ...
                'Raman Candidate Analysis';
            
            
            % ================================================================
            % INITIALIZE SETTINGS
            % ================================================================
            
            app.Settings = app.defaultAnalysisSettings();
            
            
            % ================================================================
            % INITIALIZE STATE
            % ================================================================
            
            app.WorkflowStage = ...
                "EMPTY";
            
            
            app.SessionFile = ...
                "";
            
            
            app.PreparedData = ...
                struct();
            
            
            app.AnalysisData = ...
                struct();
            
            
            app.wn = ...
                [];
            
            
            app.X_unorm = ...
                [];
            
            
            app.X_norm = ...
                [];
            
            
            app.VectorNormFactors = ...
                [];
            
            
            app.analysisGroupID = ...
                strings(0,1);
            
            
            app.analysisGroupNames = ...
                strings(0,1);
            
            
            app.groupA = ...
                "";
            
            
            app.groupB = ...
                "";
            
            
            app.ROITable = ...
                table();
            
            
            app.FeatureValues = ...
                table();
            app.MorphologyCandidates = table();
            app.MorphologyApp = [];
            
            
            app.PrePCA = ...
                struct();
            
            
            app.PCA_Full = ...
                struct();
            
            
            app.PCA_Fingerprint = ...
                struct();
            
            
            app.PCA_HighWN = ...
                struct();
            
            
            app.SelectedCandidateRow = ...
                NaN;
            
            app.ValidationCache = struct( ...
                'CandidateID',{}, ...
                'Mode',{}, ...
                'Result',{});
            
            
            app.SelectedCandidateID = ...
                "";
            
            
            app.SelectedCandidateType = ...
                "";
            
            
            app.SelectedCandidate = ...
                struct();
            
            
            app.CurrentValidation = ...
                struct();
            
            
            app.HasCurrentValidation = ...
                false;
            
            
            app.CandidateResults = struct( ...
                'CandidateID',{}, ...
                'Mode',{}, ...
                'Decision',{}, ...
                'ValidatedAt',{}, ...
                'Result',{});
            
            
            app.OutputRoot = ...
                "";
            
            
            app.EvidenceRoot = ...
                "";
            
            
            app.ExcelPath = ...
                "";
            
            
            app.AnalysisCheckpointPath = ...
                "";
            
            
            % ================================================================
            % TEXT AREA
            %
            % This is an audit / context inspector.
            % User should not manually edit it.
            % ================================================================
            
            app.TextArea.Editable = ...
                'off';
            
            
            app.TextArea.Value = ...
                { ...
                'RAMAN CANDIDATE ANALYSIS'; ...
                ''; ...
                'Status: waiting for data'; ...
                ''; ...
                'Workflow'; ...
                '--------'; ...
                '1. Load single_home_session.mat'; ...
                '2. Build exactly two analysis groups'; ...
                '3. Discover spectral candidates + PCA'; ...
                '4. Select candidate from table'; ...
                '5. Validate candidate'; ...
                '6. Confirm / reject candidate'; ...
                '7. Export Excel'; ...
                '8. Finish and save audit evidence'; ...
                ''; ...
                'Candidate feature types:'; ...
                '  INTENSITY -> SHARED validation'; ...
                '  POSITION  -> SHIFTED validation'; ...
                '  FWHM      -> WIDTH validation'; ...
                ''; ...
                'No PRESENCE validation is used.' ...
                };
            
            
            % ================================================================
            % EMPTY AXES
            %
            % These axes are reserved ONLY for candidate validation.
            % ================================================================
            
            app.clearValidationAxes();
            
            
            title( ...
                app.UIAxes, ...
                'Validation evidence 1');
            
            
            title( ...
                app.UIAxes_2, ...
                'Validation evidence 2');
            
            
            xlabel( ...
                app.UIAxes, ...
                '');
            
            
            ylabel( ...
                app.UIAxes, ...
                '');
            
            
            xlabel( ...
                app.UIAxes_2, ...
                '');
            
            
            ylabel( ...
                app.UIAxes_2, ...
                '');
            
            
            grid( ...
                app.UIAxes, ...
                'off');
            
            
            grid( ...
                app.UIAxes_2, ...
                'off');
            
            app.UITable.Data = ...
                table();
            
            
            app.UITable.Data = table();
            
            app.UITable.ColumnName = { ...
                'Candidate', ...
                'Feature type', ...
                'Mode', ...
                'Domain', ...
                'ROI low', ...
                'ROI high', ...
                'Rep. WN', ...
                'Direction', ...
                'Discovery score', ...
                'Validation', ...
                'Decision'};
            
            app.UITable.ColumnEditable = false(1,11);
            
            app.UITable.ColumnFormat = { ...
                'char','char','char','char', ...
                'numeric','numeric','numeric','char', ...
                'numeric','char','char'};
            
            app.UITable.RowName = {};
            
            
            app.UITable.RowName = ...
                {};
            
            % ================================================================
            % INITIAL BUTTON STATES
            % ================================================================
            
            app.updateWorkflowControls();
            
            
            drawnow;
            
        end

        % Button pushed function: LoaddataButton
        function LoaddataButtonPushed(app, event)
            try
                
                % ============================================================
                % SELECT SESSION
                % ============================================================
                
                [file,path] = ...
                    uigetfile( ...
                    '*.mat', ...
                    'Select RamanCube single_home_session.mat');
                
                
                if isequal(file,0)
                    
                    return
                    
                end
                
                
                sessionFile = ...
                    fullfile(path,file);
                
                
                % ============================================================
                % PREPARE
                % ============================================================
                
                Prepared = ...
                    app.prepareSingleHomeSession( ...
                    sessionFile);
                
                
                % ============================================================
                % BUILD EXACTLY TWO ANALYSIS GROUPS
                % ============================================================
                
                AnalysisData = ...
                    app.buildTwoAnalysisGroups( ...
                    Prepared);
                
                
                % ============================================================
                % OUTPUT FOLDER
                % ============================================================
                
                parentFolder = ...
                    uigetdir( ...
                    path, ...
                    'Select output parent folder');
                
                
                if isequal(parentFolder,0)
                    
                    return
                    
                end
                
                
                defaultFolderName = ...
                    char( ...
                    "CandidateAnalysis_" + ...
                    string( ...
                    datetime( ...
                    'now', ...
                    'Format','yyyyMMdd_HHmmss')));
                
                
                a = ...
                    inputdlg( ...
                    {'Analysis folder name:'}, ...
                    'Analysis output', ...
                    [1 55], ...
                    {defaultFolderName});
                
                
                if isempty(a)
                    
                    return
                    
                end
                
                
                folderName = ...
                    app.makeSafeFileName( ...
                    string(a{1}));
                
                
                if folderName == ""
                    
                    error( ...
                        'Output folder name is empty.');
                    
                end
                
                
                app.OutputRoot = ...
                    string( ...
                    fullfile( ...
                    parentFolder, ...
                    folderName));
                
                
                if ~isfolder(app.OutputRoot)
                    
                    mkdir(app.OutputRoot);
                    
                end
                
                
                app.EvidenceRoot = ...
                    string( ...
                    fullfile( ...
                    app.OutputRoot, ...
                    app.Settings.Output.EvidenceFolder));
                
                
                if ~isfolder(app.EvidenceRoot)
                    
                    mkdir(app.EvidenceRoot);
                    
                end
                
                
                discoveryRoot = ...
                    fullfile( ...
                    app.OutputRoot, ...
                    app.Settings.Output.DiscoveryFolder);
                
                
                if ~isfolder(discoveryRoot)
                    
                    mkdir(discoveryRoot);
                    
                end
                
                
                app.ExcelPath = ...
                    string( ...
                    fullfile( ...
                    app.OutputRoot, ...
                    app.Settings.Output.ExcelName));
                
                
                app.AnalysisCheckpointPath = ...
                    string( ...
                    fullfile( ...
                    app.OutputRoot, ...
                    app.Settings.Output.AnalysisCheckpointName));
                
                
                % ============================================================
                % STORE
                % ============================================================
                
                app.SessionFile = ...
                    string(sessionFile);
                
                
                app.PreparedData = ...
                    Prepared;
                
                
                app.AnalysisData = ...
                    AnalysisData;
                
                
                app.analysisGroupID = ...
                    string( ...
                    AnalysisData.GroupID(:));
                
                
                app.analysisGroupNames = ...
                    string( ...
                    AnalysisData.GroupNames(:));
                
                
                app.groupA = ...
                    app.analysisGroupNames(1);
                
                
                app.groupB = ...
                    app.analysisGroupNames(2);
                
                
                % discovery not yet run
                
                app.wn = ...
                    [];
                
                
                app.X_unorm = ...
                    [];
                
                app.ValidationCache = struct( ...
                    'CandidateID',{}, ...
                    'Mode',{}, ...
                    'Result',{});
                
                
                app.X_norm = ...
                    [];
                
                
                app.VectorNormFactors = ...
                    [];
                
                
                app.ROITable = ...
                    table();
                
                
                app.FeatureValues = ...
                    table();
                
                
                app.MorphologyCandidates = table();
                app.MorphologyApp = [];
                
                app.PrePCA = ...
                    struct();
                
                
                app.PCA_Full = ...
                    struct();
                
                
                app.PCA_Fingerprint = ...
                    struct();
                
                
                app.PCA_HighWN = ...
                    struct();
                
                
                app.SelectedCandidateRow = ...
                    NaN;
                
                
                app.SelectedCandidateID = ...
                    "";
                
                
                app.SelectedCandidateType = ...
                    "";
                
                
                app.SelectedCandidate = ...
                    struct();
                
                
                app.CurrentValidation = ...
                    struct();
                
                
                app.HasCurrentValidation = ...
                    false;
                
                
                app.CandidateResults = struct( ...
                    'CandidateID',{}, ...
                    'Mode',{}, ...
                    'Decision',{}, ...
                    'ValidatedAt',{}, ...
                    'Result',{});
                
                
                app.WorkflowStage = ...
                    "DATA_READY";
                
                
                app.clearValidationAxes();
                
                
                app.populateEmptyCandidateTable();
                
                
                app.showLoadedDataSummary();
                
                
            catch ME
                
                app.showAppError( ...
                    ME, ...
                    'Load data error');
                
            end
            
            
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button down function: UIAxes_2
        function UIAxes_2ButtonDown(app, event)
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            
        end

        % Button pushed function: ValidatecandidateButton
        function ValidatecandidateButtonPushed(app, event)
            try
                
                if ~app.requireDiscovery()
                    return
                end
                
                choice = uiconfirm( ...
                    app.UIFigure, ...
                    sprintf( ...
                    ['Validate all %d suggested candidates?\n\n' ...
                    'This calculates evidence only.\n' ...
                    'No candidate will be automatically confirmed or rejected.'], ...
                    height(app.UITable.Data)), ...
                    'Validate all candidates', ...
                    'Options',{'Validate all','Cancel'}, ...
                    'DefaultOption','Validate all', ...
                    'CancelOption','Cancel');
                
                if ~strcmp(choice,'Validate all')
                    return
                end
                
                app.validateAllCandidates();
                
            catch ME
                
                app.showAppError( ...
                    ME, ...
                    'Candidate validation error');
            end
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            try
                
                pending = 0;
                
                if istable(app.UITable.Data) && ...
                        ~isempty(app.UITable.Data)
                    
                    validationState = ...
                        string(app.UITable.Data.Validation);
                    
                    decisionState = ...
                        string(app.UITable.Data.Decision);
                    
                    isValidated = ...
                        startsWith(validationState,"VALIDATED");
                    
                    pending = ...
                        nnz( ...
                        decisionState == "PENDING" & ...
                        isValidated);
                end
                
                
                if pending > 0
                    
                    msg = ...
                        sprintf( ...
                        [ ...
                        '%d validated candidate(s) are still PENDING.\n\n' ...
                        'You may still save and finish.' ...
                        ], ...
                        pending);
                    
                else
                    
                    msg = ...
                        [ ...
                        'Save the analysis checkpoint, evidence, and current audit ' ...
                        'before closing?' ...
                        ];
                    
                end
                
                
                choice = ...
                    uiconfirm( ...
                    app.UIFigure, ...
                    msg, ...
                    'Finish analysis', ...
                    'Options', ...
                    { ...
                    'Save and finish', ...
                    'Finish without saving', ...
                    'Cancel' ...
                    }, ...
                    'DefaultOption', ...
                    'Save and finish', ...
                    'CancelOption', ...
                    'Cancel');
                
                
                if strcmp(choice,'Cancel')
                    
                    return
                    
                end
                
                
                if strcmp(choice,'Save and finish')
                    
                    app.saveAnalysisCheckpoint();
                    
                    
                    if ~isempty(app.CandidateResults)
                        
                        app.exportAnalysisWorkbook();
                        
                    end
                    
                end
                
                
                delete(app);
                
                
            catch ME
                
                app.showAppError( ...
                    ME, ...
                    'Finish error');
                
            end
        end

        % Button pushed function: DiscovercandidatesButton
        function DiscovercandidatesButtonPushed(app, event)
            try
                
                if ~app.requireLoadedData()
                    return
                end
                
                % Close stale morphology app if required
                if ~isempty(app.MorphologyApp)
                    try
                        if isvalid(app.MorphologyApp)
                            delete(app.MorphologyApp);
                        end
                    catch
                    end
                end
                
                % New discovery invalidates previous candidate states
                app.MorphologyCandidates = table();
                app.CandidateResults = struct( ...
                    'CandidateID',{}, ...
                    'Mode',{}, ...
                    'Decision',{}, ...
                    'ValidatedAt',{}, ...
                    'Result',{});
                
                app.ValidationCache = struct( ...
                    'CandidateID',{}, ...
                    'Mode',{}, ...
                    'Result',{});
                
                app.CurrentValidation = struct();
                app.HasCurrentValidation = false;
                
                app.setTextArea({ ...
                    'DISCOVERY IN PROGRESS'; ...
                    '────────────────────────────────'; ...
                    ''; ...
                    '1. Broad intensity candidate screening'; ...
                    '2. PCA calculation'; ...
                    '3. Human morphology screening'; ...
                    ''; ...
                    'Discovery creates SUGGESTED candidates only.' ...
                    });
                
                drawnow;
                
                % ================================================================
                % AUTOMATIC INTENSITY DISCOVERY
                % ================================================================
                
                app.runDiscovery();
                
                % ================================================================
                % PCA
                % ================================================================
                
                app.runAllPCA();
                
                % Do NOT finalize candidate table yet.
                app.populateEmptyCandidateTable();
                
                % ================================================================
                % MORPHOLOGY SCREENING
                % ================================================================
                
                payload = struct();
                
                payload.Wavenumber = app.wn;
                payload.XNorm = app.X_norm;
                payload.XUnnorm = app.X_unorm;
                payload.GroupID = app.analysisGroupID;
                payload.GroupA = app.groupA;
                payload.GroupB = app.groupB;
                
                app.WorkflowStage = "MORPHOLOGY_REVIEW";
                
                app.setTextArea({ ...
                    'INTENSITY DISCOVERY COMPLETE'; ...
                    '────────────────────────────────'; ...
                    ''; ...
                    sprintf('Automatic intensity ROIs: %d',height(app.ROITable)); ...
                    ''; ...
                    'Morphology screening is now active.'; ...
                    'Review POSITION and FWHM candidates in the child window.'; ...
                    ''; ...
                    'Press Finish in Morphology spectral check'; ...
                    'to build the complete suggested candidate table.' ...
                    });
                
                app.MorphologyApp = check_shift();
                app.MorphologyApp.receiveParentPayload(app,payload);
                
            catch ME
                
                app.showAppError( ...
                    ME, ...
                    'Candidate discovery error');
            end
        end

        % Button pushed function: ConfirmcandidatesButton
        function ConfirmcandidatesButtonPushed(app, event)
            try
                
                if ~app.requireCurrentValidation()
                    
                    return
                    
                end
                
                
                R = ...
                    app.CurrentValidation;
                
                
                choice = ...
                    uiconfirm( ...
                    app.UIFigure, ...
                    sprintf( ...
                    [ ...
                    'Candidate: %s\n' ...
                    'Mode: %s\n' ...
                    'Evidence: %d/5\n' ...
                    'State: %s\n\n' ...
                    'Record your review decision.' ...
                    ], ...
                    R.CandidateID, ...
                    R.Mode, ...
                    R.Score, ...
                    R.Status), ...
                    'Candidate review', ...
                    'Options', ...
                    { ...
                    'Confirm', ...
                    'Reject', ...
                    'Keep pending' ...
                    }, ...
                    'DefaultOption', ...
                    'Keep pending', ...
                    'CancelOption', ...
                    'Keep pending');
                
                
                switch choice
                    
                    case 'Confirm'
                        
                        R.Decision = ...
                            "CONFIRMED";
                        
                        
                    case 'Reject'
                        
                        R.Decision = ...
                            "REJECTED";
                        
                        
                    otherwise
                        
                        R.Decision = ...
                            "PENDING";
                        
                end
                
                
                app.CurrentValidation = ...
                    R;
                app.cacheValidationResult(R);
                
                
                app.upsertCandidateResult( ...
                    R);
                
                
                row = ...
                    app.SelectedCandidateRow;
                
                
                app.UITable.Data.Decision(row) = ...
                    R.Decision;
                
                
                app.UITable.Data.Validation(row) = ...
                    sprintf( ...
                    'VALIDATED | %d/5 | %s', ...
                    R.Score, ...
                    R.Status);
                
                
                app.saveCurrentCandidateEvidence( ...
                    R);
                
                
                app.WorkflowStage = ...
                    "REVIEWED";
                
                
                app.showValidationText( ...
                    R);
                
                
            catch ME
                
                app.showAppError( ...
                    ME, ...
                    'Candidate review error');
                
            end
            
        end

        % Button pushed function: ExcelexportButton
        function ExcelexportButtonPushed(app, event)
            try
                
                if isempty(app.CandidateResults)
                    
                    uialert( ...
                        app.UIFigure, ...
                        [ ...
                        'No reviewed candidate results are available.' newline ...
                        'Validate and review candidates first.' ...
                        ], ...
                        'Nothing to export', ...
                        'Icon','warning');
                    
                    return
                    
                end
                
                
                if app.OutputRoot == ""
                    
                    folder = ...
                        uigetdir( ...
                        pwd, ...
                        'Select analysis output folder');
                    
                    
                    if isequal(folder,0)
                        
                        return
                        
                    end
                    
                    
                    app.OutputRoot = ...
                        string(folder);
                    
                    
                    app.ExcelPath = ...
                        string( ...
                        fullfile( ...
                        folder, ...
                        app.Settings.Output.ExcelName));
                    
                end
                
                
                app.exportAnalysisWorkbook();
                
                
                uialert( ...
                    app.UIFigure, ...
                    sprintf( ...
                    'Excel audit exported:\n%s', ...
                    app.ExcelPath), ...
                    'Excel export complete', ...
                    'Icon','success');
                
                
            catch ME
                
                app.showAppError( ...
                    ME, ...
                    'Excel export error');
                
            end
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            if isempty(event.Indices)
                return
            end
            
            T = app.UITable.Data;
            
            if isempty(T) || ~istable(T)
                return
            end
            
            row = event.Indices(1);
            
            if row < 1 || row > height(T)
                return
            end
            
            app.SelectedCandidateRow = row;
            app.SelectedCandidateID = string(T.Candidate(row));
            app.SelectedCandidateType = upper(string(T.Mode(row)));
            
            featureType = upper(string(T.FeatureType(row)));
            
            % ================================================================
            % FULL DISCOVERY RECORD
            % ================================================================
            
            if featureType == "INTENSITY"
                
                idx = find( ...
                    string(app.ROITable.ROIID) == ...
                    app.SelectedCandidateID,1);
                
                if isempty(idx)
                    app.SelectedCandidate = struct();
                else
                    app.SelectedCandidate = table2struct( ...
                        app.ROITable(idx,:), ...
                        'ToScalar',true);
                end
                
            else
                
                idx = find( ...
                    string(app.MorphologyCandidates.Candidate) == ...
                    app.SelectedCandidateID,1);
                
                if isempty(idx)
                    app.SelectedCandidate = struct();
                else
                    app.SelectedCandidate = table2struct( ...
                        app.MorphologyCandidates(idx,:), ...
                        'ToScalar',true);
                end
            end
            
            % ================================================================
            % RESTORE PREVIOUS VALIDATION IF IT EXISTS
            % ================================================================
            
            [hasCache,R] = ...
                app.getCachedValidation(app.SelectedCandidateID);
            
            if hasCache
                
                % Synchronize decision from table
                currentDecision = ...
                    string(T.Decision(row));
                
                if currentDecision == ""
                    currentDecision = "PENDING";
                end
                
                R.Decision = currentDecision;
                
                app.CurrentValidation = R;
                app.HasCurrentValidation = true;
                
                if currentDecision == "CONFIRMED" || ...
                        currentDecision == "REJECTED"
                    
                    app.WorkflowStage = "REVIEWED";
                    
                else
                    
                    app.WorkflowStage = "VALIDATED";
                end
                
                app.showValidationText(R);
                
                switch upper(string(R.Mode))
                    
                    case "SHARED"
                        app.plotSharedValidationFourAxes(R);
                        
                    case "SHIFTED"
                        app.plotShiftValidationFourAxes(R);
                        
                    case "FWHM"
                        app.plotWidthValidationFourAxes(R);
                end
                
            else
                
                app.CurrentValidation = struct();
                app.HasCurrentValidation = false;
                app.WorkflowStage = "CANDIDATE_SELECTED";
                
                app.clearValidationAxes();
                app.showSelectedCandidateSummary();
            end
        end

        % Button down function: UIAxes_3
        function UIAxes_3ButtonDown(app, event)
            
        end

        % Button down function: UIAxes_4
        function UIAxes_4ButtonDown(app, event)
            
        end

        % Button pushed function: AddcandidatesButton
        function AddcandidatesButtonPushed(app, event)
            try
                
                % ================================================================
                % CUSTOM CANDIDATE REQUIRES COMPLETED DISCOVERY
                %
                % Reason:
                % validation needs X_norm / X_unorm / PCA models.
                % ================================================================
                
                if ~app.requireDiscovery()
                    return
                end
                
                % ================================================================
                % SELECT FEATURE TYPE
                % ================================================================
                
                choice = uiconfirm( ...
                    app.UIFigure, ...
                    [ ...
                    'Add a manually observed spectral candidate.' newline newline ...
                    'This does NOT confirm the feature.' newline ...
                    'The candidate will still undergo the same validation ' ...
                    'as automatically detected candidates.' ...
                    ], ...
                    'Add custom candidate', ...
                    'Options', ...
                    { ...
                    'Intensity', ...
                    'Position shift', ...
                    'FWHM', ...
                    'Cancel' ...
                    }, ...
                    'DefaultOption','Intensity', ...
                    'CancelOption','Cancel');
                
                if strcmp(choice,'Cancel')
                    return
                end
                
                switch choice
                    
                    case 'Intensity'
                        
                        app.addCustomIntensityCandidate();
                        
                    case 'Position shift'
                        
                        app.addCustomPositionCandidate();
                        
                    case 'FWHM'
                        
                        app.addCustomFWHMCandidate();
                end
                
            catch ME
                
                app.showAppError( ...
                    ME, ...
                    'Add custom candidate error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Axis 1')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes_2 = uiaxes(app.GridLayout);
            title(app.UIAxes_2, 'Axis 2')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes_2ButtonDown, true);
            app.UIAxes_3 = uiaxes(app.GridLayout);
            title(app.UIAxes_3, 'Axis 3')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.ButtonDownFcn = createCallbackFcn(app, @UIAxes_3ButtonDown, true);
            app.UIAxes_4 = uiaxes(app.GridLayout);
            title(app.UIAxes_4, 'Axis 4')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_4.ButtonDownFcn = createCallbackFcn(app, @UIAxes_4ButtonDown, true);
            app.TextArea = uitextarea(app.GridLayout);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.SettingsPanel = uipanel(app.GridLayout);
            app.SettingsPanel.Title = 'Settings';
            app.GridLayout2 = uigridlayout(app.SettingsPanel);
            app.LoaddataButton = uibutton(app.GridLayout2, 'push');
            app.LoaddataButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddataButtonPushed, true);
            app.LoaddataButton.Text = 'Load data';
            app.DiscovercandidatesButton = uibutton(app.GridLayout2, 'push');
            app.DiscovercandidatesButton.ButtonPushedFcn = createCallbackFcn(app, @DiscovercandidatesButtonPushed, true);
            app.DiscovercandidatesButton.Text = 'Discover candidates';
            app.ValidatecandidateButton = uibutton(app.GridLayout2, 'push');
            app.ValidatecandidateButton.ButtonPushedFcn = createCallbackFcn(app, @ValidatecandidateButtonPushed, true);
            app.ValidatecandidateButton.Text = 'Validate candidate';
            app.ConfirmcandidatesButton = uibutton(app.GridLayout2, 'push');
            app.ConfirmcandidatesButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmcandidatesButtonPushed, true);
            app.ConfirmcandidatesButton.Text = 'Confirm candidates';
            app.ExcelexportButton = uibutton(app.GridLayout2, 'push');
            app.ExcelexportButton.ButtonPushedFcn = createCallbackFcn(app, @ExcelexportButtonPushed, true);
            app.ExcelexportButton.Text = 'Excel export';
            app.FinishButton = uibutton(app.GridLayout2, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.AddcandidatesButton = uibutton(app.GridLayout2, 'push');
            app.AddcandidatesButton.ButtonPushedFcn = createCallbackFcn(app, @AddcandidatesButtonPushed, true);
            app.AddcandidatesButton.Text = 'Add candidates';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = candidate_confirm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end