classdef check_shift < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        MorphologyspectralcheckLabel  matlab.ui.control.Label
        GridLayout4                   matlab.ui.container.GridLayout
        FeaturesDropDown              matlab.ui.control.DropDown
        FeaturesDropDownLabel         matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        ThresholdDropDown             matlab.ui.control.DropDown
        ThresholdDropDownLabel        matlab.ui.control.Label
        ZoomButton                    matlab.ui.control.Button
        FinishButton                  matlab.ui.control.Button
        AddcandidatesButton           matlab.ui.control.Button
        OffsetCheckBox                matlab.ui.control.CheckBox
        UITable                       matlab.ui.control.Table
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ParentApp
        Payload struct = struct()
        
        MeanA double = []
        MeanB double = []
        
        PeakPairs table = table()
        
        CurrentFeature string = "POSITION"
        SelectedRow double = NaN
        
        SelectedPosition logical = false(0,1)
        SelectedFWHM logical = false(0,1)
        
        IsZoomed logical = false
        ThresholdMode string = "DEFAULT"
        
        DefaultProminenceFactor double = 2.0
        DefaultMinPeakDistance double = 5
        DefaultSmoothWidth_cm1 double = 8
        
        CurrentProminenceFactor double = 2.0
        CurrentMinPeakDistance double = 5
        CurrentSmoothWidth_cm1 double = 8
        
        CustomRules table = table()
    end
    
    methods (Access = public)
        
        function receiveParentPayload(app,parentApp,payload)
            
            % ================================================================
            % RECEIVE DATA FROM PARENT APP
            % ================================================================
            
            if nargin < 3
                error('Parent app and morphology payload are required.');
            end
            
            if isempty(parentApp) || ~isvalid(parentApp)
                error('Parent application handle is invalid.');
            end
            
            if ~isstruct(payload)
                error('Morphology payload must be a struct.');
            end
            
            app.ParentApp = parentApp;
            app.Payload = payload;
            
            % Initialize morphology screening only after payload is received
            app.initializeMorphologyCheck();
            
        end
        
    end


    methods (Access = private)
        
        function initializeMorphologyCheck(app)
            required = ["Wavenumber","XNorm","GroupID","GroupA","GroupB"];
            for k = 1:numel(required)
                if ~isfield(app.Payload,required(k))
                    error('Morphology payload missing field: %s',required(k));
                end
            end
            
            wn = double(app.Payload.Wavenumber(:));
            X = double(app.Payload.XNorm);
            gid = string(app.Payload.GroupID(:));
            gA = string(app.Payload.GroupA);
            gB = string(app.Payload.GroupB);
            
            if size(X,1) ~= numel(wn)
                error('XNorm rows do not match Wavenumber.');
            end
            if size(X,2) ~= numel(gid)
                error('XNorm columns do not match GroupID.');
            end
            
            idxA = gid == gA;
            idxB = gid == gB;
            
            if ~any(idxA) || ~any(idxB)
                error('Both analysis groups must contain spectra.');
            end
            
            % Mean spectra only for morphology screening
            app.MeanA = mean(X(:,idxA),2,'omitnan');
            app.MeanB = mean(X(:,idxB),2,'omitnan');
            
            PA = app.detectMeanPeaks(wn,app.MeanA);
            PB = app.detectMeanPeaks(wn,app.MeanB);
            
            % Pair corresponding peaks across groups
            pairTolerance_cm1 = 20;
            app.PeakPairs = app.pairMeanPeaks(PA,PB,pairTolerance_cm1);
            
            n = height(app.PeakPairs);
            app.SelectedPosition = false(n,1);
            app.SelectedFWHM = false(n,1);
            
            app.FeaturesDropDown.Items = {'POSITION','FWHM'};
            app.FeaturesDropDown.Value = 'POSITION';
            app.CurrentFeature = "POSITION";
            app.ThresholdMode = "DEFAULT";
            
            app.CurrentProminenceFactor = ...
                app.DefaultProminenceFactor;
            
            app.CurrentMinPeakDistance = ...
                app.DefaultMinPeakDistance;
            
            app.CurrentSmoothWidth_cm1 = ...
                app.DefaultSmoothWidth_cm1;
            
            app.CustomRules = table();
            
            app.ThresholdDropDown.Items = ...
                {'Default','Weaker','Stronger','Custom'};
            
            app.ThresholdDropDown.Value = ...
                'Default';
            
            app.refreshMorphologyTable();
            
            cla(app.UIAxes);
            title(app.UIAxes,sprintf('%s vs %s | Select a peak row',gA,gB), ...
                'Interpreter','none');
            xlabel(app.UIAxes,'Raman shift (cm^{-1})');
            ylabel(app.UIAxes,'Vector-normalized intensity');
            box(app.UIAxes,'on');
        end
        
        function P = detectMeanPeaks(app,wn,y)
            
            wn = double(wn(:));
            y = double(y(:));
            
            domainRanges = { ...
                [400 1800], ...
                [2800 3100]};
            
            allWN = [];
            allHeight = [];
            allWidth = [];
            allProm = [];
            
            for dmn = 1:numel(domainRanges)
                
                range = domainRanges{dmn};
                
                m = ...
                    wn >= range(1) & ...
                    wn <= range(2);
                
                x = wn(m);
                yy = y(m);
                
                if numel(x) < 7
                    continue
                end
                
                % ================================================================
                % DETECT DOMAIN BY SEGMENTS
                %
                % Default/Weaker/Stronger:
                % one setting for whole domain.
                %
                % Custom:
                % custom regions override selected WN ranges;
                % remaining regions still use Default.
                % ================================================================
                
                segments = ...
                    app.buildDetectionSegments( ...
                    min(x), ...
                    max(x));
                
                for s = 1:height(segments)
                    
                    segMask = ...
                        x >= segments.LowWN(s) & ...
                        x <= segments.HighWN(s);
                    
                    xs = x(segMask);
                    ys = yy(segMask);
                    
                    if numel(xs) < 7
                        continue
                    end
                    
                    smoothWidth_cm1 = ...
                        segments.SmoothWidth(s);
                    
                    prominenceFactor = ...
                        segments.ProminenceFactor(s);
                    
                    minPeakDistance = ...
                        segments.MinPeakDistance(s);
                    
                    wnSpacing = ...
                        median(abs(diff(xs)),'omitnan');
                    
                    smoothPoints = ...
                        max(3,round( ...
                        smoothWidth_cm1 / ...
                        max(wnSpacing,eps)));
                    
                    if mod(smoothPoints,2) == 0
                        smoothPoints = smoothPoints + 1;
                    end
                    
                    if smoothPoints >= numel(ys)
                        smoothPoints = max(3,numel(ys)-1);
                        
                        if mod(smoothPoints,2) == 0
                            smoothPoints = smoothPoints - 1;
                        end
                    end
                    
                    if smoothPoints < 3
                        continue
                    end
                    
                    ySmooth = ...
                        smoothdata( ...
                        ys, ...
                        'sgolay', ...
                        smoothPoints);
                    
                    dd = diff(ySmooth);
                    
                    dMed = median(dd,'omitnan');
                    
                    noiseSigma = ...
                        1.4826 * ...
                        median(abs(dd-dMed),'omitnan') / ...
                        sqrt(2);
                    if ~isfinite(noiseSigma) || noiseSigma <= eps
                        noiseSigma = ...
                            std(dd,0,'omitnan') / sqrt(2);
                    end
                    
                    if ~isfinite(noiseSigma) || noiseSigma <= eps
                        noiseSigma = eps;
                    end
                    
                    minProminence = ...
                        prominenceFactor * noiseSigma;
                    
                    [pk,loc,w,prom] = ...
                        findpeaks( ...
                        ySmooth,xs, ...
                        'MinPeakProminence',minProminence, ...
                        'MinPeakDistance',minPeakDistance, ...
                        'WidthReference','halfheight');
                    
                    allWN = [allWN;loc(:)]; %#ok<AGROW>
                    allHeight = [allHeight;pk(:)]; %#ok<AGROW>
                    allWidth = [allWidth;w(:)]; %#ok<AGROW>
                    allProm = [allProm;prom(:)]; %#ok<AGROW>
                end
            end
            
            % Remove duplicates caused by adjacent custom/default segments
            if ~isempty(allWN)
                
                [allWN,order] = sort(allWN);
                
                allHeight = allHeight(order);
                allWidth = allWidth(order);
                allProm = allProm(order);
                
                keep = true(size(allWN));
                
                for k = 2:numel(allWN)
                    if abs(allWN(k)-allWN(k-1)) < 1
                        if allProm(k) > allProm(k-1)
                            keep(k-1) = false;
                        else
                            keep(k) = false;
                        end
                    end
                end
                
                allWN = allWN(keep);
                allHeight = allHeight(keep);
                allWidth = allWidth(keep);
                allProm = allProm(keep);
            end
            
            n = numel(allWN);
            
            P = table( ...
                (1:n)', ...
                allWN(:), ...
                allHeight(:), ...
                allWidth(:), ...
                allProm(:), ...
                'VariableNames', ...
                {'PeakIndex','Position','PeakHeight','FWHM','Prominence'});
            
            if ~isempty(P)
                P = sortrows(P,'Position');
            end
        end


        function T = pairMeanPeaks(app,PA,PB,tolerance)
            %#ok<INUSD>
            
            if isempty(PA) || isempty(PB)
                T = table();
                return
            end
            
            usedB = false(height(PB),1);
            
            WN_ID = strings(0,1);
            RepresentativeWN = zeros(0,1);
            PositionA = zeros(0,1);
            PositionB = zeros(0,1);
            FWHM_A = zeros(0,1);
            FWHM_B = zeros(0,1);
            ProminenceA = zeros(0,1);
            ProminenceB = zeros(0,1);
            Domain = strings(0,1);
            
            row = 0;
            
            for i = 1:height(PA)
                posA = PA.Position(i);
                
                % Pair only inside same Raman domain
                if posA <= 1800
                    validB = PB.Position >= 400 & PB.Position <= 1800;
                elseif posA >= 2800
                    validB = PB.Position >= 2800 & PB.Position <= 3100;
                else
                    continue
                end
                
                dist = abs(PB.Position-posA);
                dist(~validB | usedB) = Inf;
                
                [bestDist,j] = min(dist);
                
                if isempty(j) || ~isfinite(bestDist) || bestDist > tolerance
                    continue
                end
                
                usedB(j) = true;
                row = row + 1;
                
                centerWN = mean([PA.Position(i),PB.Position(j)]);
                
                WN_ID(row,1) = sprintf('WN_%.1f',centerWN);
                RepresentativeWN(row,1) = centerWN;
                PositionA(row,1) = PA.Position(i);
                PositionB(row,1) = PB.Position(j);
                FWHM_A(row,1) = PA.FWHM(i);
                FWHM_B(row,1) = PB.FWHM(j);
                ProminenceA(row,1) = PA.Prominence(i);
                ProminenceB(row,1) = PB.Prominence(j);
                
                if centerWN <= 1800
                    Domain(row,1) = "Fingerprint";
                else
                    Domain(row,1) = "High-WN";
                end
            end
            
            T = table( ...
                WN_ID,RepresentativeWN, ...
                PositionA,PositionB, ...
                FWHM_A,FWHM_B, ...
                ProminenceA,ProminenceB,Domain);
            
            if ~isempty(T)
                T = sortrows(T,'RepresentativeWN');
            end
        end
        
        function refreshMorphologyTable(app)
            
            if isempty(app.PeakPairs)
                app.UITable.Data = table();
                return
            end
            
            P = app.PeakPairs;
            
            switch app.CurrentFeature
                
                case "POSITION"
                    
                    valueA = P.PositionA;
                    valueB = P.PositionB;
                    
                    Difference = ...
                        valueB-valueA;
                    
                case "FWHM"
                    
                    valueA = P.FWHM_A;
                    valueB = P.FWHM_B;
                    
                    Difference = ...
                        valueB-valueA;
                    
                otherwise
                    return
            end
            
            app.UITable.Data = table( ...
                P.WN_ID, ...
                valueA, ...
                valueB, ...
                Difference, ...
                'VariableNames', ...
                { ...
                'WN_ID', ...
                'Group_A', ...
                'Group_B', ...
                'Difference'});
            
            app.UITable.ColumnName = { ...
                'WN ID', ...
                char(app.Payload.GroupA), ...
                char(app.Payload.GroupB), ...
                'B - A'};
            
            app.UITable.ColumnEditable = ...
                [false false false false];
            
            app.refreshSelectionStyles();
        end
        
        
        function plotSelectedMorphology(app)
            if ~isfinite(app.SelectedRow) || ...
                    app.SelectedRow < 1 || ...
                    app.SelectedRow > height(app.PeakPairs)
                return
            end
            
            r = app.SelectedRow;
            P = app.PeakPairs(r,:);
            wn = double(app.Payload.Wavenumber(:));
            
            yA = app.MeanA;
            yB = app.MeanB;
            
            if app.OffsetCheckBox.Value
                allY = [yA(:);yB(:)];
                span = max(allY)-min(allY);
                
                if ~isfinite(span) || span <= eps
                    span = max(abs(allY));
                end
                if ~isfinite(span) || span <= eps
                    span = 1;
                end
                
                yBPlot = yB + 0.30*span;
            else
                yBPlot = yB;
            end
            
            ax = app.UIAxes;
            cla(ax);
            
            plot(ax,wn,yA,'LineWidth',1.4);
            hold(ax,'on');
            plot(ax,wn,yBPlot,'LineWidth',1.4);
            
            % Two detected mean-peak positions
            xline(ax,P.PositionA,'--',sprintf('A %.1f',P.PositionA), ...
                'LabelVerticalAlignment','middle');
            xline(ax,P.PositionB,':',sprintf('B %.1f',P.PositionB), ...
                'LabelVerticalAlignment','middle');
            
            hold(ax,'off');
            
            xlabel(ax,'Raman shift (cm^{-1})');
            ylabel(ax,'Vector-normalized intensity');
            
            legend(ax, ...
                {char(app.Payload.GroupA),char(app.Payload.GroupB)}, ...
                'Interpreter','none','Location','best');
            
            if app.CurrentFeature == "POSITION"
                ttl = sprintf( ...
                    '%s | Position A %.2f | B %.2f | B-A = %+.2f cm^{-1}', ...
                    P.WN_ID,P.PositionA,P.PositionB,P.PositionB-P.PositionA);
            else
                ttl = sprintf( ...
                    '%s | FWHM A %.2f | B %.2f cm^{-1}', ...
                    P.WN_ID,P.FWHM_A,P.FWHM_B);
            end
            
            title(ax,ttl,'Interpreter','none');
            box(ax,'on');
            
            if app.IsZoomed
                c = P.RepresentativeWN;
                xlim(ax,[max(min(wn),c-150),min(max(wn),c+150)]);
            elseif P.Domain == "Fingerprint"
                xlim(ax,[400 1800]);
            else
                xlim(ax,[2800 3100]);
            end
        end
        
        
        function refreshSelectionStyles(app)
            removeStyle(app.UITable);
            
            if isempty(app.PeakPairs)
                return
            end
            
            if app.CurrentFeature == "POSITION"
                selected = app.SelectedPosition;
            else
                selected = app.SelectedFWHM;
            end
            
            rows = find(selected);
            
            if isempty(rows)
                return
            end
            
            selectedStyle = uistyle( ...
                'BackgroundColor',[0.78 0.94 0.84], ...
                'FontWeight','bold');
            
            for k = 1:numel(rows)
                addStyle(app.UITable,selectedStyle,'row',rows(k));
            end
        end


        function Results = buildMorphologyResults(app)
            
            Results = table();
            
            % ================================================================
            % POSITION
            % ================================================================
            
            idx = find(app.SelectedPosition);
            
            if ~isempty(idx)
                
                P = app.PeakPairs(idx,:);
                n = height(P);
                
                Candidate = strings(n,1);
                
                for k = 1:n
                    Candidate(k) = sprintf( ...
                        'POS_%03d_%.1f', ...
                        idx(k),P.RepresentativeWN(k));
                end
                
                T = table();
                
                T.Candidate = Candidate;
                T.FeatureType = repmat("POSITION",n,1);
                T.Mode = repmat("SHIFTED",n,1);
                T.Domain = P.Domain;
                T.RepresentativeWN = P.RepresentativeWN;
                
                T.GroupAValue = P.PositionA;
                T.GroupBValue = P.PositionB;
                
                T.ExpectedPositionA = P.PositionA;
                T.ExpectedPositionB = P.PositionB;
                
                T.FWHM_A = P.FWHM_A;
                T.FWHM_B = P.FWHM_B;
                
                T.ProminenceA = P.ProminenceA;
                T.ProminenceB = P.ProminenceB;
                
                T.SearchLow = ...
                    min(P.PositionA,P.PositionB)-25;
                
                T.SearchHigh = ...
                    max(P.PositionA,P.PositionB)+25;
                
                Results = [Results;T];
            end
            
            % ================================================================
            % FWHM
            % ================================================================
            
            idx = find(app.SelectedFWHM);
            
            if ~isempty(idx)
                
                P = app.PeakPairs(idx,:);
                n = height(P);
                
                Candidate = strings(n,1);
                
                for k = 1:n
                    Candidate(k) = sprintf( ...
                        'FWHM_%03d_%.1f', ...
                        idx(k),P.RepresentativeWN(k));
                end
                
                T = table();
                
                T.Candidate = Candidate;
                T.FeatureType = repmat("FWHM",n,1);
                T.Mode = repmat("FWHM",n,1);
                T.Domain = P.Domain;
                T.RepresentativeWN = P.RepresentativeWN;
                
                T.GroupAValue = P.FWHM_A;
                T.GroupBValue = P.FWHM_B;
                
                % Important for individual-spectrum detection later
                T.ExpectedPositionA = P.PositionA;
                T.ExpectedPositionB = P.PositionB;
                
                T.FWHM_A = P.FWHM_A;
                T.FWHM_B = P.FWHM_B;
                
                T.ProminenceA = P.ProminenceA;
                T.ProminenceB = P.ProminenceB;
                
                T.SearchLow = ...
                    min(P.PositionA,P.PositionB)-25;
                
                T.SearchHigh = ...
                    max(P.PositionA,P.PositionB)+25;
                
                Results = [Results;T];
            end
        end


        function T = buildDetectionSegments(app,domainLow,domainHigh)
            
            % ================================================================
            % BASE SETTINGS
            % ================================================================
            
            baseProm = app.CurrentProminenceFactor;
            baseDist = app.CurrentMinPeakDistance;
            baseSmooth = app.CurrentSmoothWidth_cm1;
            
            % No custom rules
            if isempty(app.CustomRules)
                
                T = table( ...
                    domainLow, ...
                    domainHigh, ...
                    baseProm, ...
                    baseDist, ...
                    baseSmooth, ...
                    'VariableNames', ...
                    { ...
                    'LowWN', ...
                    'HighWN', ...
                    'ProminenceFactor', ...
                    'MinPeakDistance', ...
                    'SmoothWidth'});
                
                return
            end
            
            % ================================================================
            % BREAK DOMAIN INTO SEGMENTS
            % ================================================================
            
            rules = app.CustomRules;
            
            rules = rules( ...
                rules.HighWN >= domainLow & ...
                rules.LowWN <= domainHigh,:);
            
            if isempty(rules)
                
                T = table( ...
                    domainLow,domainHigh, ...
                    baseProm,baseDist,baseSmooth, ...
                    'VariableNames', ...
                    {'LowWN','HighWN','ProminenceFactor', ...
                    'MinPeakDistance','SmoothWidth'});
                
                return
            end
            
            rules.LowWN = ...
                max(rules.LowWN,domainLow);
            
            rules.HighWN = ...
                min(rules.HighWN,domainHigh);
            
            breakPoints = unique([ ...
                domainLow; ...
                domainHigh; ...
                rules.LowWN; ...
                rules.HighWN]);
            
            rows = [];
            
            for k = 1:numel(breakPoints)-1
                
                low = breakPoints(k);
                high = breakPoints(k+1);
                
                if high <= low
                    continue
                end
                
                center = (low+high)/2;
                
                prom = baseProm;
                dist = baseDist;
                smooth = baseSmooth;
                
                idx = find( ...
                    center >= rules.LowWN & ...
                    center <= rules.HighWN, ...
                    1,'last');
                
                if ~isempty(idx)
                    
                    prom = rules.ProminenceFactor(idx);
                    dist = rules.MinPeakDistance(idx);
                    smooth = rules.SmoothWidth(idx);
                end
                
                rows = [rows; ...
                    low high prom dist smooth]; %#ok<AGROW>
            end
            
            T = array2table( ...
                rows, ...
                'VariableNames', ...
                {'LowWN','HighWN','ProminenceFactor', ...
                'MinPeakDistance','SmoothWidth'});
        end


        function rerunMorphologyDetection(app)
            
            wn = ...
                double(app.Payload.Wavenumber(:));
            
            PA = ...
                app.detectMeanPeaks( ...
                wn,app.MeanA);
            
            PB = ...
                app.detectMeanPeaks( ...
                wn,app.MeanB);
            
            pairTolerance_cm1 = 20;
            
            newPairs = ...
                app.pairMeanPeaks( ...
                PA,PB,pairTolerance_cm1);
            
            % ================================================================
            % IMPORTANT
            %
            % Threshold changes can alter row count/order.
            % Previous selected flags therefore cannot safely be reused by row.
            % We remap selections by representative WN.
            % ================================================================
            
            oldPairs = app.PeakPairs;
            oldPosSelected = app.SelectedPosition;
            oldWidthSelected = app.SelectedFWHM;
            
            app.PeakPairs = newPairs;
            
            n = height(newPairs);
            
            app.SelectedPosition = false(n,1);
            app.SelectedFWHM = false(n,1);
            
            if ~isempty(oldPairs)
                
                oldPosWN = ...
                    oldPairs.RepresentativeWN(oldPosSelected);
                
                oldWidthWN = ...
                    oldPairs.RepresentativeWN(oldWidthSelected);
                
                for k = 1:n
                    
                    if ~isempty(oldPosWN)
                        app.SelectedPosition(k) = ...
                            any(abs( ...
                            oldPosWN - ...
                            newPairs.RepresentativeWN(k)) <= 3);
                    end
                    
                    if ~isempty(oldWidthWN)
                        app.SelectedFWHM(k) = ...
                            any(abs( ...
                            oldWidthWN - ...
                            newPairs.RepresentativeWN(k)) <= 3);
                    end
                end
            end
            
            app.SelectedRow = NaN;
            
            app.refreshMorphologyTable();
            
            cla(app.UIAxes);
            
            title(app.UIAxes, ...
                sprintf( ...
                '%s | detected paired peaks = %d', ...
                app.ThresholdMode, ...
                height(app.PeakPairs)), ...
                'Interpreter','none');
            
            xlabel(app.UIAxes,'Raman shift (cm^{-1})');
            ylabel(app.UIAxes,'Vector-normalized intensity');
            box(app.UIAxes,'on');
        end


        function configureCustomThreshold(app)
            
            defaultLow = 400;
            defaultHigh = 1800;
            
            if isfinite(app.SelectedRow) && ...
                    app.SelectedRow >= 1 && ...
                    app.SelectedRow <= height(app.PeakPairs)
                
                c = ...
                    app.PeakPairs.RepresentativeWN( ...
                    app.SelectedRow);
                
                defaultLow = max(400,c-100);
                defaultHigh = min(3100,c+100);
            end
            
            answer = inputdlg( ...
                { ...
                'WN low (cm^-1):', ...
                'WN high (cm^-1):', ...
                'Prominence factor x noise:', ...
                'Minimum peak distance (cm^-1):', ...
                'Smoothing width (cm^-1):'}, ...
                'Custom morphology detection region', ...
                [1 52], ...
                { ...
                num2str(defaultLow), ...
                num2str(defaultHigh), ...
                '1.5', ...
                '4', ...
                '6'});
            
            if isempty(answer)
                
                app.ThresholdDropDown.Value = ...
                    char(app.ThresholdMode);
                
                return
            end
            
            lowWN = str2double(answer{1});
            highWN = str2double(answer{2});
            promFactor = str2double(answer{3});
            minDistance = str2double(answer{4});
            smoothWidth = str2double(answer{5});
            
            values = ...
                [lowWN highWN promFactor minDistance smoothWidth];
            
            if any(~isfinite(values))
                uialert(app.UIFigure, ...
                    'All custom threshold values must be numeric.', ...
                    'Invalid custom threshold', ...
                    'Icon','error');
                return
            end
            
            if lowWN >= highWN
                uialert(app.UIFigure, ...
                    'WN low must be smaller than WN high.', ...
                    'Invalid custom region', ...
                    'Icon','error');
                return
            end
            
            if promFactor <= 0 || ...
                    minDistance <= 0 || ...
                    smoothWidth <= 0
                
                uialert(app.UIFigure, ...
                    'Threshold parameters must be greater than zero.', ...
                    'Invalid custom threshold', ...
                    'Icon','error');
                return
            end
            
            % Keep base spectrum detection at DEFAULT outside custom ranges
            app.CurrentProminenceFactor = ...
                app.DefaultProminenceFactor;
            
            app.CurrentMinPeakDistance = ...
                app.DefaultMinPeakDistance;
            
            app.CurrentSmoothWidth_cm1 = ...
                app.DefaultSmoothWidth_cm1;
            
            newRule = table( ...
                lowWN, ...
                highWN, ...
                promFactor, ...
                minDistance, ...
                smoothWidth, ...
                'VariableNames', ...
                { ...
                'LowWN', ...
                'HighWN', ...
                'ProminenceFactor', ...
                'MinPeakDistance', ...
                'SmoothWidth'});
            
            app.CustomRules = ...
                [app.CustomRules;newRule];
            
            app.ThresholdMode = "CUSTOM";
            
            app.rerunMorphologyDetection();
            
            msg = sprintf( ...
                ['Custom detection rule added:\n\n' ...
                'Region: %.1f - %.1f cm^-1\n' ...
                'Prominence: %.2f x noise\n' ...
                'Min distance: %.2f cm^-1\n' ...
                'Smooth width: %.2f cm^-1\n\n' ...
                'Outside this region, Default settings remain active.'], ...
                lowWN,highWN,promFactor,minDistance,smoothWidth);
            
            uialert(app.UIFigure,msg, ...
                'Custom threshold applied', ...
                'Icon','success');
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FeaturesDropDown
        function FeaturesDropDownValueChanged(app, event)
            app.CurrentFeature = upper(string(app.FeaturesDropDown.Value));
            app.SelectedRow = NaN;
            app.IsZoomed = false;
            app.ZoomButton.Text = 'Zoom';
            app.refreshMorphologyTable();
            cla(app.UIAxes);
            title(app.UIAxes,'Select a row to inspect morphology');
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            selection = app.UITable.Selection;
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            if isempty(event.Indices)
                return
            end
            
            row = event.Indices(1);
            
            if row < 1 || row > height(app.PeakPairs)
                return
            end
            
            app.SelectedRow = row;
            app.IsZoomed = false;
            app.ZoomButton.Text = 'Zoom';
            app.plotSelectedMorphology();
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: OffsetCheckBox
        function OffsetCheckBoxValueChanged(app, event)
            if isfinite(app.SelectedRow)
                app.plotSelectedMorphology();
            end
            
        end

        % Button pushed function: AddcandidatesButton
        function AddcandidatesButtonPushed(app, event)
            if ~isfinite(app.SelectedRow)
                uialert(app.UIFigure, ...
                    'Select a peak row first.', ...
                    'Peak required','Icon','warning');
                return
            end
            
            r = app.SelectedRow;
            
            if app.CurrentFeature == "POSITION"
                app.SelectedPosition(r) = ~app.SelectedPosition(r);
            else
                app.SelectedFWHM(r) = ~app.SelectedFWHM(r);
            end
            
            app.refreshSelectionStyles();
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            Results = app.buildMorphologyResults();
            
            if ~isempty(app.ParentApp) && isvalid(app.ParentApp)
                app.ParentApp.receiveMorphologyCandidates(Results);
            end
            
            delete(app);
        end

        % Button pushed function: ZoomButton
        function ZoomButtonPushed(app, event)
            if ~isfinite(app.SelectedRow)
                return
            end
            
            app.IsZoomed = ~app.IsZoomed;
            
            if app.IsZoomed
                app.ZoomButton.Text = 'Full view';
            else
                app.ZoomButton.Text = 'Zoom';
            end
            
            app.plotSelectedMorphology();
        end

        % Value changed function: ThresholdDropDown
        function ThresholdDropDownValueChanged(app, event)
            mode = ...
                upper(string(app.ThresholdDropDown.Value));
            
            switch mode
                
                case "DEFAULT"
                    
                    app.ThresholdMode = "DEFAULT";
                    
                    app.CurrentProminenceFactor = ...
                        app.DefaultProminenceFactor;
                    
                    app.CurrentMinPeakDistance = ...
                        app.DefaultMinPeakDistance;
                    
                    app.CurrentSmoothWidth_cm1 = ...
                        app.DefaultSmoothWidth_cm1;
                    
                    app.CustomRules = table();
                    
                    app.rerunMorphologyDetection();
                    
                    
                case "WEAKER"
                    
                    app.ThresholdMode = "WEAKER";
                    
                    app.CurrentProminenceFactor = 1.4;
                    app.CurrentMinPeakDistance = 3;
                    app.CurrentSmoothWidth_cm1 = 5;
                    
                    app.CustomRules = table();
                    
                    app.rerunMorphologyDetection();
                    
                    
                case "STRONGER"
                    
                    app.ThresholdMode = "STRONGER";
                    
                    app.CurrentProminenceFactor = 3.0;
                    app.CurrentMinPeakDistance = 7;
                    app.CurrentSmoothWidth_cm1 = 10;
                    
                    app.CustomRules = table();
                    
                    app.rerunMorphologyDetection();
                    
                    
                case "CUSTOM"
                    
                    app.configureCustomThreshold();
                    
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Axis')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.Panel);
            app.OffsetCheckBox = uicheckbox(app.GridLayout3);
            app.OffsetCheckBox.ValueChangedFcn = createCallbackFcn(app, @OffsetCheckBoxValueChanged, true);
            app.OffsetCheckBox.Text = 'Offset';
            app.AddcandidatesButton = uibutton(app.GridLayout3, 'push');
            app.AddcandidatesButton.ButtonPushedFcn = createCallbackFcn(app, @AddcandidatesButtonPushed, true);
            app.AddcandidatesButton.Text = 'Add candidates';
            app.FinishButton = uibutton(app.GridLayout3, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.ZoomButton = uibutton(app.GridLayout3, 'push');
            app.ZoomButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomButtonPushed, true);
            app.ZoomButton.Text = 'Zoom';
            app.ThresholdDropDownLabel = uilabel(app.GridLayout3);
            app.ThresholdDropDownLabel.Text = 'Threshold';
            app.ThresholdDropDown = uidropdown(app.GridLayout3);
            app.ThresholdDropDown.Items = {'Defaut', 'Weaker', 'Stronger', 'Custom'};
            app.ThresholdDropDown.ValueChangedFcn = createCallbackFcn(app, @ThresholdDropDownValueChanged, true);
            app.ThresholdDropDown.Value = 'Defaut';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.FeaturesDropDownLabel = uilabel(app.GridLayout4);
            app.FeaturesDropDownLabel.Text = 'Features';
            app.FeaturesDropDown = uidropdown(app.GridLayout4);
            app.FeaturesDropDown.ValueChangedFcn = createCallbackFcn(app, @FeaturesDropDownValueChanged, true);
            app.MorphologyspectralcheckLabel = uilabel(app.GridLayout);
            app.MorphologyspectralcheckLabel.Text = 'Morphology spectral check';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = check_shift

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end