classdef scanpretreat < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        CosmicremovalTab              matlab.ui.container.Tab
        GridLayout                    matlab.ui.container.GridLayout
        GridLayout12                  matlab.ui.container.GridLayout
        SaveimagesButton              matlab.ui.control.Button
        FinishButton                  matlab.ui.control.Button
        TextArea_5                    matlab.ui.control.TextArea
        CorrectioncosmicspikePanel    matlab.ui.container.Panel
        GridLayout5                   matlab.ui.container.GridLayout
        ModesDropDown_2               matlab.ui.control.DropDown
        ModesDropDown_2Label          matlab.ui.control.Label
        ReviewButton                  matlab.ui.control.Button
        ApplyforallButton             matlab.ui.control.Button
        TrialcorrectionButton         matlab.ui.control.Button
        MethodsDropDown_2             matlab.ui.control.DropDown
        MethodsDropDown_2Label        matlab.ui.control.Label
        DetectioncosmicspikePanel     matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        DeletespectraButton           matlab.ui.control.Button
        Button                        matlab.ui.control.Button
        ManualdetectButton            matlab.ui.control.Button
        ConfirmfinalcosmiclistButton  matlab.ui.control.Button
        Button_4                      matlab.ui.control.Button
        Button_2                      matlab.ui.control.Button
        Button_3                      matlab.ui.control.Button
        CosmicdetectionButton         matlab.ui.control.Button
        ModesDropDown                 matlab.ui.control.DropDown
        ModesDropDownLabel            matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        Tree                          matlab.ui.container.Tree
        Node                          matlab.ui.container.TreeNode
        Node2                         matlab.ui.container.TreeNode
        Node3                         matlab.ui.container.TreeNode
        Node4                         matlab.ui.container.TreeNode
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        ArtifactremovalTab            matlab.ui.container.Tab
        GridLayout6                   matlab.ui.container.GridLayout
        ParametersPanel               matlab.ui.container.Panel
        GridLayout7                   matlab.ui.container.GridLayout
        ColorsDropDown                matlab.ui.control.DropDown
        ColorsDropDownLabel           matlab.ui.control.Label
        ViewDropDown                  matlab.ui.control.DropDown
        ViewDropDownLabel             matlab.ui.control.Label
        FinishButton_2                matlab.ui.control.Button
        SaveimagesButton_2            matlab.ui.control.Button
        DetectartifactButton          matlab.ui.control.Button
        TextArea_2                    matlab.ui.control.TextArea
        ModeDropDown                  matlab.ui.control.DropDown
        ModeDropDownLabel             matlab.ui.control.Label
        UIAxes4                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        mainApp
        
        % ===== RamanCube unified folder system =====
        % These paths must be received from scanspectra/Mainhome.
        DataInputRoot char = ''
        DataOutputRoot char = ''
        
        ScanInputRoot char = ''
        ScanOutputRoot char = ''
        ScanWorkspaceRoot char = ''
        
        ProjectRoot char = ''
        Paths struct = struct()
        
        
        % ===== Session metadata from Mainhome/scanspectra =====
        SessionInfo struct = struct()
        % ===== Cosmic workflow state =====
        CosmicConfirmed logical = false
        LastCosmicStatePath char = ''
        LastCosmicEvidenceFolder char = ''
        % ===== Cosmic evidence / QC package =====
        EvidenceTopN double = 10
        EvidenceRandomN double = 10
        EvidenceBorderlineN double = 10
        EvidenceRandomSeed double = 1
        SourceRoot char = ''
        TraceRoot char = ''
        DataRoot char = ''
        ScanList struct = struct('Name',{},'MatPath',{},'Folder',{})
        CurrentScanIndex double = NaN
        CurrentScanName char = ''
        CurrentMatPath char = ''
        RamanData struct = struct()
        CubeOriginal double = []
        CorrectedCube double = []
        Wavenumber double = []
        CurrentROIMask logical = logical.empty
        ROIReady logical = false
        ROIDataMap
        TempMask logical = logical.empty
        TempScore single = single.empty
        ConfirmedMask logical = logical.empty
        CandidateTable table = table()
        CurrentCandidateIndex double = NaN
        % =============================================================
        % COSMIC RESOLUTION-AWARE STATE
        % =============================================================
        
        SpectralResolution double = NaN
        SpectralSampling double = NaN
        SpectralResolutionSource char = ''
        
        DeletedSpectrumMask2D logical = logical.empty
        DeletedSpectrumTable table = table()
        
        CosmicReviewFinalized logical = false
        ManualDetectionActive logical = false
        
        LastTrialBeforeSpectrum double = []
        LastTrialAfterSpectrum double = []
        LastTrialQC struct = struct()
        
        % Detection region expansion controls.
        CosmicSupportReturnK double = 2.5
        CosmicMergeDistanceR double = 3.0
        CosmicMaxAutomaticWidthR double = 3.0
        IsUpdatingTree logical = false
        DetectionParams struct = struct( ...
            'Method', "Derivative + local residual", ...
            'DerivativeOrder', 2, ...
            'ThresholdK', 8, ...
            'MinJumpK', 6, ...
            'LocalWindow', 9, ...
            'MaxSpikeWidth', 3, ...
            'MergeDistanceR', 3, ...
            'MaxAutomaticWidthR', 3, ...
            'SupportReturnK', 2.5)
        LastDetectionParamsAfterCorrection struct = struct()
        LastDetectionParamSignature char = ''
        CorrectParams struct = struct('Method',"Linear interpolation",'ExpandPoints',0,'SupportPoints',3,'MedianWindow',7)
        CorrectParamsChecked logical = false
        CorrectionTrialMode logical = false
        CorrectionReady logical = false
        LastTrialCandidateIndex double = NaN
        BackgroundMeanSpectrum double = []
        BackgroundStdSpectrum double = []
        BackgroundSampleLinearIdx double = []
        MaxBackgroundSpectra double = 100
        MaxStatsSpectra double = 3000
        MaxReviewPlotSpectra double = 1500
        AgreeNodeStyle
        IgnoreNodeStyle
        PendingNodeStyle
        CorrectedNodeStyle
        LastSavedPayload struct = struct()
        ImageSaveRoot char = ''
        % ===== Artifact removal state =====
        ArtifactParams struct = struct()
        ArtifactModeName char = 'Safe recommended'
        ArtifactReady logical = false
        ArtifactConfirmed logical = false
        ArtifactMask2D logical = logical.empty
        ValidPixelMask2D logical = logical.empty
        ArtifactDisplayMapRaw double = []
        ArtifactDisplayMapClean double = []
        ArtifactDisplayLabel char = ''
        ArtifactStripeScoreMap double = []
        ArtifactBandConsistencyMap double = []
        ArtifactSpectralZMap double = []
        ArtifactStrongSeedMask logical = logical.empty
        ArtifactWeakSupportMask logical = logical.empty
        ArtifactHysteresisMask logical = logical.empty
        ArtifactRowBandMask logical = logical.empty
        ArtifactRowEvidence struct = struct()
        ArtifactTable table = table()
        ArtifactBandMaps double = []
        ArtifactBandLabels cell = {}
        ArtifactZStack double = []
        ArtifactDisplayInpaintCube double = []
        ArtifactDisplayInpaintInfo struct = struct()
        ArtifactDataRoot char = ''
        ArtifactImageRoot char = ''
        CosmicDetectionMode char = 'Medium'
        CosmicCorrectionParameterMode char = 'Medium'
        CosmicWNMode char = 'Full wavenumber range'
        CosmicWNMask logical = logical.empty
        CosmicWNIncludeRanges double = []
        CosmicWNExcludeRanges double = []
        % ===== Fast cosmic candidate paging / QC =====
        CandidatePageSize double = 200
        CurrentCandidatePage double = 1
        CandidateNumPages double = 1
        CandidateVisibleIndices double = []
        CandidateSummary struct = struct()
        ArtifactViewMode char = 'Interpolated clean map'
        ArtifactColorMap char = 'hot'
        
        % =============================================================
        % BACKGROUND REMOVAL STATE
        % =============================================================
        
        BackgroundReady logical = false
        BackgroundValidated logical = false
        BackgroundConfirmed logical = false
        
        BackgroundInputCube double = []
        BackgroundValidMask2D logical = logical.empty
        BackgroundArtifactMask2D logical = logical.empty
        
        BackgroundPreviewMap double = []
        BackgroundPreviewLabel char = ''
        
        BackgroundLabelMap double = []
        BackgroundConfidenceMap double = []
        
        % Cluster information:
        % ID
        % Name
        % ParentID
        % PixelIdx
        % Class
        % Active
        % MeanSpectrum
        % StdSpectrum
        BackgroundClusters struct = struct( ...
            'ID', {}, ...
            'Name', {}, ...
            'ParentID', {}, ...
            'PixelIdx', {}, ...
            'Class', {}, ...
            'Active', {}, ...
            'MeanSpectrum', {}, ...
            'StdSpectrum', {}, ...
            'Metrics', {})
        BackgroundNextClusterID double = 1
        BackgroundSelectedClusterIDs double = []
        
        BackgroundCellMask2D logical = logical.empty
        BackgroundBackgroundMask2D logical = logical.empty
        BackgroundUncertainMask2D logical = logical.empty
        BackgroundUnassignedMask2D logical = logical.empty
        
        % Manual override:
        % 0 = no override
        % 1 = cell
        % 2 = background
        % 3 = uncertain
        BackgroundManualClassMap uint8 = uint8.empty
        
        BackgroundMethod char = 'PCA + K-means'
        BackgroundMorphology char = 'Automatic / mixed'
        
        BackgroundK double = 4
        BackgroundVarianceThreshold double = 95
        BackgroundMaxPC double = 15
        BackgroundReplicates double = 10
        BackgroundRandomSeed double = 1
        
        BackgroundNumPCUsed double = 0
        BackgroundExplained double = []
        BackgroundPCAcoeff double = []
        BackgroundPCAmu double = []
        
        % =============================================================
        % BACKGROUND CLUSTERING MODEL AND SCIENTIFIC QC
        % =============================================================
        
        % PCA score of every finite valid pixel.
        % Row order corresponds to BackgroundMetricPixelIdx.
        BackgroundAllScores double = []
        
        % Current active cluster label of every metric pixel.
        BackgroundMetricLabels double = []
        
        % Spatial linear indices corresponding to rows of
        % BackgroundAllScores and BackgroundMetricLabels.
        BackgroundMetricPixelIdx double = []
        
        % K-means centroids in PCA score space.
        BackgroundCentroids double = []
        
        % Training subset used to fit PCA and K-means.
        BackgroundTrainingLocalIdx double = []
        BackgroundTrainingLinearIdx double = []
        
        % Silhouette value for every metric pixel.
        BackgroundSilhouetteValues double = []
        
        % Repeated-run stability per metric pixel.
        % Range: 0 to 1.
        BackgroundPixelStability double = []
        
        % Global clustering summary.
        BackgroundGlobalMetrics struct = struct()
        
        % One structure for every active cluster.
        BackgroundClusterMetrics struct = struct( ...
            'ClusterID', {}, ...
            'ClusterName', {}, ...
            'PixelCount', {}, ...
            'PixelFraction', {}, ...
            'MeanSilhouette', {}, ...
            'MedianSilhouette', {}, ...
            'P10Silhouette', {}, ...
            'NegativeSilhouetteFraction', {}, ...
            'MedianConfidence', {}, ...
            'LowConfidenceFraction', {}, ...
            'MedianDistance', {}, ...
            'P90Distance', {}, ...
            'NormalizedDispersion', {}, ...
            'MedianSpectralAngle', {}, ...
            'P90SpectralAngle', {}, ...
            'Stability', {}, ...
            'ConnectedComponents', {}, ...
            'LargestComponentFraction', {}, ...
            'IsolatedPixelFraction', {}, ...
            'SplitPriority', {}, ...
            'SplitLevel', {}, ...
            'Recommendation', {}, ...
            'Reasons', {})
        
        % Most recent trial split result.
        BackgroundLastSplitTrial struct = struct()
        
        % Metric computation controls.
        BackgroundMetricMaxSpectra double = 12000
        BackgroundSilhouetteMaxSpectra double = 6000
        BackgroundStabilityRuns double = 10
        BackgroundMetricRandomSeed double = 11
        
        BackgroundTrainingMaxSpectra double = 20000
        
        BackgroundUndoStack cell = {}
        BackgroundRedoStack cell = {}
        BackgroundMaxHistory double = 30
        
        BackgroundLastSavedPath char = ''
        BackgroundImageRoot char = ''
        BackgroundDataRoot char = ''
        BackgroundStateRoot char = ''
        
        BackgroundIsUpdatingTree logical = false
        % ===== Manual cosmic ROI re-detection =====
        ManualCosmicSpatialMask logical = logical.empty
        ManualCosmicWNMask logical = logical.empty
        ManualCosmicROIReady logical = false
        ManualCosmicROICount double = 0
        ManualCosmicROISummary cell = {}
        
        % =============================================================
        % AUXILIARY COSMIC CLEANING IN SILENT REGION
        % =============================================================
        
        AuxCosmicEnabled logical = true
        AuxCosmicRange double = [1800 2750]
        AuxCosmicMask logical = logical.empty
        AuxCosmicCandidateTable table = table()
        AuxCosmicCorrectedCount double = 0
        TrialCorrectedCube double = []


    end


    methods (Access = public)
        
        function setMainApp(app, mainAppIn)
            
            % =============================================================
            % STORE PARENT SCANSPECTRA APP
            % =============================================================
            app.mainApp = mainAppIn;
            
            if isempty(mainAppIn)
                return
            end
            
            try
                parentValid = isvalid(mainAppIn);
            catch
                parentValid = false;
            end
            
            if ~parentValid
                return
            end
            
            % =============================================================
            % RECEIVE SESSION AND FOLDERS FROM SCANSPECTRA
            % =============================================================
            try
                if ismethod(mainAppIn, 'buildScanHomeSessionInfo')
                    
                    scanSession = ...
                        mainAppIn.buildScanHomeSessionInfo();
                    
                    app.receiveScanHomeSession( ...
                        scanSession, ...
                        mainAppIn);
                end
                
            catch ME
                warning( ...
                    'RamanCube:ScanPretreatParentLinkFailed', ...
                    '%s', ...
                    ME.message);
            end
        end


        function receiveOriginalScan(app, payload, mainAppIn)
            
            % =============================================================
            % 1. VALIDATE INPUT
            % =============================================================
            
            if nargin < 2 || ...
                    isempty(payload) || ...
                    ~isstruct(payload)
                
                error( ...
                    'scanpretreat received an invalid original scan payload.');
            end
            
            if nargin >= 3
                app.mainApp = mainAppIn;
            end
            
            requiredFields = { ...
                'ScanName', ...
                'ScanID', ...
                'Cube', ...
                'Wavenumber', ...
                'ValidMask'};
            
            for k = 1:numel(requiredFields)
                
                if ~isfield(payload, requiredFields{k})
                    
                    error( ...
                        'Original scan payload is missing field: %s', ...
                        requiredFields{k});
                end
            end
            
            % =============================================================
            % 2. VALIDATE CUBE
            % =============================================================
            
            cube = ...
                double(payload.Cube);
            
            if isempty(cube) || ...
                    ndims(cube) ~= 3
                
                error( ...
                    'Original Raman scan must contain a 3-D cube.');
            end
            
            [ny, nx, nWn] = ...
                size(cube);
            
            wn = ...
                double(payload.Wavenumber(:));
            
            if numel(wn) ~= nWn
                
                error([ ...
                    'Original scan wavenumber count does not match ' ...
                    'the cube spectral dimension.']);
            end
            
            finiteMask = ...
                all(isfinite(cube), 3);
            
            validMask = ...
                logical(payload.ValidMask);
            
            if ~isequal(size(validMask), [ny nx])
                
                validMask = ...
                    finiteMask;
            else
                
                validMask = ...
                    validMask & finiteMask;
            end
            
            if ~any(validMask(:))
                
                error( ...
                    'Original Raman scan contains no valid pixel.');
            end
            
            % =============================================================
            % 3. INITIALIZE APP STATE
            % =============================================================
            
            app.RamanData = ...
                payload;
            
            app.CubeOriginal = ...
                cube;
            
            app.CorrectedCube = ...
                [];
            
            app.Wavenumber = ...
                wn;
            
            app.CurrentScanName = ...
                char(string(payload.ScanName));
            
            if isfield(payload, 'SourceMatPath')
                app.CurrentMatPath = ...
                    char(string(payload.SourceMatPath));
            else
                app.CurrentMatPath = '';
            end
            
            app.CurrentScanIndex = ...
                NaN;
            
            % Initially use the valid scan mask as the spatial scope.
            % This is not yet considered a user-drawn ROI.
            app.CurrentROIMask = ...
                validMask;
            
            app.ROIReady = ...
                false;
            
            % Restore a previously drawn ROI for the same scan when available.
            if isempty(app.ROIDataMap) || ...
                    ~isa(app.ROIDataMap, 'containers.Map')
                
                app.ROIDataMap = ...
                    containers.Map( ...
                    'KeyType', 'char', ...
                    'ValueType', 'any');
            end
            
            if isKey(app.ROIDataMap, app.CurrentScanName)
                
                storedROI = ...
                    logical(app.ROIDataMap(app.CurrentScanName));
                
                if isequal(size(storedROI), [ny nx]) && ...
                        any(storedROI(:))
                    
                    app.CurrentROIMask = ...
                        storedROI & validMask;
                    
                    app.ROIReady = ...
                        true;
                end
            end
            
            app.DeletedSpectrumMask2D = ...
                false(ny, nx);
            
            app.DeletedSpectrumTable = ...
                table();
            
            % =============================================================
            % 4. RESOLVE SPECTRAL RESOLUTION
            % =============================================================
            
            app.resolveSpectralResolution(payload);
            
            % =============================================================
            % REBUILD RESOLUTION-AWARE DETECTION PRESET
            %
            % The UI value is retained, but the preset is recalculated using
            % the spectral sampling and resolution of the current scan.
            % =============================================================
            
            selectedMode = ...
                char(app.ModesDropDown.Value);
            
            if isempty(selectedMode) || ...
                    ~any(strcmp(selectedMode, ...
                    {'Heavy','Medium','Light','Manual'}))
                
                selectedMode = ...
                    'Medium';
                
                app.ModesDropDown.Value = ...
                    selectedMode;
            end
            
            app.CosmicDetectionMode = ...
                selectedMode;
            
            if ~strcmp(selectedMode, 'Manual')
                
                app.DetectionParams = ...
                    app.getCosmicDetectionPresetParams( ...
                    selectedMode);
                
            else
                
                app.DetectionParams = ...
                    app.sanitizeDetectionParams( ...
                    app.DetectionParams);
                
                app.DetectionParams.Mode = ...
                    "Manual";
            end
            
            app.ModesDropDown.Tooltip = ...
                app.getCosmicDetectionModeTooltip( ...
                selectedMode);
            
            % =============================================================
            % 5. RESET COSMIC STATE
            % =============================================================
            
            app.TempMask = ...
                logical.empty;
            
            app.TempScore = ...
                single.empty;
            
            app.ConfirmedMask = ...
                logical.empty;
            
            app.CandidateTable = ...
                table();
            
            app.CurrentCandidateIndex = ...
                NaN;
            
            app.CurrentCandidatePage = ...
                1;
            
            app.CandidateNumPages = ...
                1;
            
            app.CandidateVisibleIndices = ...
                [];
            
            app.CandidateSummary = ...
                struct();
            
            app.CosmicConfirmed = ...
                false;
            
            app.CosmicReviewFinalized = ...
                false;
            
            app.CorrectionTrialMode = ...
                false;
            
            app.CorrectionReady = ...
                false;
            
            app.CorrectParamsChecked = ...
                false;
            
            app.LastTrialCandidateIndex = ...
                NaN;
            
            app.LastTrialBeforeSpectrum = ...
                [];
            
            app.LastTrialAfterSpectrum = ...
                [];
            
            app.LastTrialQC = ...
                struct();
            
            app.BackgroundMeanSpectrum = ...
                [];
            
            app.BackgroundStdSpectrum = ...
                [];
            
            app.BackgroundSampleLinearIdx = ...
                [];
            
            app.CosmicWNMask = ...
                true(nWn, 1);
            
            app.CosmicWNMode = ...
                'Full wavenumber range';
            
            app.CosmicWNIncludeRanges = ...
                [];
            
            app.CosmicWNExcludeRanges = ...
                [];
            
            app.ManualCosmicSpatialMask = ...
                logical.empty;
            
            app.ManualCosmicWNMask = ...
                logical.empty;
            
            app.ManualCosmicROIReady = ...
                false;
            
            app.ManualCosmicROICount = ...
                0;
            app.AuxCosmicMask = ...
                logical.empty;
            
            app.AuxCosmicCandidateTable = ...
                table();
            
            app.AuxCosmicCorrectedCount = ...
                0;
            
            app.TrialCorrectedCube = ...
                [];
            % =============================================================
            % 6. RESET DOWNSTREAM STATES
            % =============================================================
            
            app.resetArtifactForRerun();
            
            app.BackgroundReady = ...
                false;
            
            app.BackgroundValidated = ...
                false;
            
            app.BackgroundConfirmed = ...
                false;
            
            % =============================================================
            % 7. PREPARE UI
            % =============================================================
            
            app.TabGroup.SelectedTab = ...
                app.CosmicremovalTab;
            
            app.updateScanInformationBox();
            
            app.populateCandidateTree(false);
            
            app.plotRawMap();
            
            app.setCosmicWorkflowControls('loaded');
            
            app.setText({ ...
                'COSMIC REMOVAL READY'; ...
                '================================'; ...
                ''; ...
                ['Scan: ' app.CurrentScanName]; ...
                sprintf('Valid spectra: %d / %d', ...
                nnz(validMask), ...
                numel(validMask)); ...
                sprintf('Spectral sampling: %.4g cm^{-1}/point', ...
                app.SpectralSampling); ...
                sprintf('Spectral resolution R: %.4g cm^{-1}', ...
                app.SpectralResolution); ...
                ['Resolution source: ' ...
                app.SpectralResolutionSource]; ...
                ''; ...
                'Next step:'; ...
                'Select detection mode and press Cosmic detection.'});
            
            app.UIFigure.Visible = ...
                'on';
            
            drawnow;
        end


        function receiveScanHomeSession(app, scanSession, mainAppIn)
            
            if nargin < 2 || ...
                    isempty(scanSession) || ...
                    ~isstruct(scanSession)
                
                error( ...
                    'scanpretreat requires a valid scan session.');
            end
            
            if nargin >= 3
                app.mainApp = mainAppIn;
            end
            
            app.SessionInfo = ...
                scanSession;
            
            % =============================================================
            % RESET PATHS
            % =============================================================
            
            app.ProjectRoot = '';
            
            app.DataInputRoot = '';
            app.DataOutputRoot = '';
            
            app.ScanInputRoot = '';
            app.ScanOutputRoot = '';
            app.ScanWorkspaceRoot = '';
            
            % =============================================================
            % PROJECT ROOT
            % =============================================================
            
            if isfield(scanSession, 'ProjectRoot') && ...
                    ~isempty(scanSession.ProjectRoot)
                
                app.ProjectRoot = ...
                    char(string(scanSession.ProjectRoot));
            end
            
            % =============================================================
            % DATA ROOTS
            % =============================================================
            
            if isfield(scanSession, 'DataInputRoot') && ...
                    ~isempty(scanSession.DataInputRoot)
                
                app.DataInputRoot = ...
                    char(string(scanSession.DataInputRoot));
                
            elseif isfield(scanSession, 'InputRoot') && ...
                    ~isempty(scanSession.InputRoot)
                
                app.DataInputRoot = ...
                    char(string(scanSession.InputRoot));
            end
            
            if isfield(scanSession, 'DataOutputRoot') && ...
                    ~isempty(scanSession.DataOutputRoot)
                
                app.DataOutputRoot = ...
                    char(string(scanSession.DataOutputRoot));
                
            elseif isfield(scanSession, 'OutputRoot') && ...
                    ~isempty(scanSession.OutputRoot)
                
                app.DataOutputRoot = ...
                    char(string(scanSession.OutputRoot));
            end
            
            % =============================================================
            % FALLBACK PROJECT ROOT
            % =============================================================
            
            if isempty(app.ProjectRoot)
                
                if ~isempty(app.DataInputRoot)
                    
                    app.ProjectRoot = ...
                        fileparts(app.DataInputRoot);
                    
                elseif ~isempty(app.DataOutputRoot)
                    
                    app.ProjectRoot = ...
                        fileparts(app.DataOutputRoot);
                end
            end
            
            % =============================================================
            % SCAN ROOTS
            % =============================================================
            
            if isfield(scanSession, 'ScanInputRoot') && ...
                    ~isempty(scanSession.ScanInputRoot)
                
                app.ScanInputRoot = ...
                    char(string(scanSession.ScanInputRoot));
                
            elseif ~isempty(app.DataInputRoot)
                
                app.ScanInputRoot = ...
                    fullfile( ...
                    app.DataInputRoot, ...
                    'scan');
            end
            
            if isfield(scanSession, 'ScanOutputRoot') && ...
                    ~isempty(scanSession.ScanOutputRoot)
                
                app.ScanOutputRoot = ...
                    char(string(scanSession.ScanOutputRoot));
                
            elseif ~isempty(app.DataOutputRoot)
                
                app.ScanOutputRoot = ...
                    fullfile( ...
                    app.DataOutputRoot, ...
                    'scan');
            end
            
            if isfield(scanSession, 'ScanWorkspaceRoot') && ...
                    ~isempty(scanSession.ScanWorkspaceRoot)
                
                app.ScanWorkspaceRoot = ...
                    char(string(scanSession.ScanWorkspaceRoot));
                
            elseif ~isempty(app.ScanOutputRoot)
                
                app.ScanWorkspaceRoot = ...
                    fullfile( ...
                    app.ScanOutputRoot, ...
                    'workspaces');
            end
            
            % =============================================================
            % VALIDATION
            % =============================================================
            
            if isempty(app.ProjectRoot) || ...
                    isempty(app.DataInputRoot) || ...
                    isempty(app.DataOutputRoot) || ...
                    isempty(app.ScanInputRoot) || ...
                    isempty(app.ScanOutputRoot) || ...
                    isempty(app.ScanWorkspaceRoot)
                
                error( ...
                    ['scanpretreat received incomplete RamanCube folder ' ...
                    'information from scanspectra.']);
            end
            
            % =============================================================
            % INITIALIZE STORAGE
            % =============================================================
            
            app.initializeProjectPaths();
            
            app.initializeScanPretreat();
            
            % =============================================================
            % DISPLAY
            % =============================================================
            
            userText = ...
                'unknown_user';
            
            if isfield(scanSession, 'User') && ...
                    ~isempty(scanSession.User)
                
                userText = ...
                    char(string(scanSession.User));
            end
            
            app.setText({ ...
                'SCAN PRETREAT READY'; ...
                '================================'; ...
                ''; ...
                ['User: ' userText]; ...
                ['Project root: ' app.ProjectRoot]; ...
                ['Input scan folder: ' app.ScanInputRoot]; ...
                ['Workspace root: ' app.ScanWorkspaceRoot]; ...
                ''; ...
                'Press Load data to begin.'});
            
        end
        
        
    end


    methods (Access = private)
        
        function initializeScanPretreat(app)
            
            % =============================================================
            % 1. UI CONTROLS
            % =============================================================
            
            app.initializeCosmicControls();
            
            if isempty(app.ROIDataMap) || ...
                    ~isa(app.ROIDataMap, 'containers.Map')
                
                app.ROIDataMap = ...
                    containers.Map( ...
                    'KeyType', 'char', ...
                    'ValueType', 'any');
            end
            
            % =============================================================
            % 2. PROJECT FOLDERS
            %
            % Only initialize paths when a session has already supplied them.
            % =============================================================
            
            hasFolderSession = ...
                ~isempty(app.DataInputRoot) && ...
                ~isempty(app.DataOutputRoot) && ...
                ~isempty(app.ScanInputRoot) && ...
                ~isempty(app.ScanOutputRoot);
            
            if hasFolderSession
                app.initializeProjectPaths();
            end
            
            % =============================================================
            % 3. DETECTION DEFAULT
            % =============================================================
            
            app.CosmicDetectionMode = ...
                'Medium';
            
            % At this stage spectral resolution may already be available.
            if isfinite(app.SpectralSampling) && ...
                    app.SpectralSampling > 0 && ...
                    isfinite(app.SpectralResolution) && ...
                    app.SpectralResolution > 0
                
                app.DetectionParams = ...
                    app.getCosmicDetectionPresetParams('Medium');
            else
                
                app.DetectionParams = ...
                    app.sanitizeDetectionParams( ...
                    app.DetectionParams);
            end
            
            % =============================================================
            % 4. CORRECTION DEFAULT
            % =============================================================
            
            app.CorrectParams = ...
                app.getCosmicCorrectionPresetParams( ...
                'Medium', ...
                app.MethodsDropDown_2.Value);
            
            app.CorrectParamsChecked = ...
                false;
            
            app.CosmicCorrectionParameterMode = ...
                'Medium';
            
            app.MethodsDropDown_2.Tooltip = ...
                app.getCorrectionMethodTooltip( ...
                app.MethodsDropDown_2.Value);
            
            % =============================================================
            % 5. OTHER TABS
            % =============================================================
            
            app.initializeArtifactTab();
            % app.initializeBackgroundTab();
            
            % =============================================================
            % 6. TREE STYLES
            % =============================================================
            
            app.AgreeNodeStyle = ...
                uistyle( ...
                'FontColor', [0 0.45 0], ...
                'FontWeight', 'bold');
            
            app.IgnoreNodeStyle = ...
                uistyle( ...
                'FontColor', [0.65 0 0], ...
                'FontWeight', 'bold');
            
            app.PendingNodeStyle = ...
                uistyle( ...
                'FontColor', [0.25 0.25 0.25]);
            
            app.CorrectedNodeStyle = ...
                uistyle( ...
                'FontColor', [0 0.25 0.85], ...
                'FontWeight', 'bold');
            
            try
                delete(app.Tree.Children);
            catch
            end
            
            app.clearAxes();
            
            if isempty(app.CubeOriginal)
                app.setCosmicWorkflowControls('empty');
            else
                app.setCosmicWorkflowControls('loaded');
            end
        end


        function initializeProjectPaths(app)
            
            if isempty(app.ProjectRoot) || ...
                    isempty(app.DataInputRoot) || ...
                    isempty(app.DataOutputRoot) || ...
                    isempty(app.ScanInputRoot) || ...
                    isempty(app.ScanOutputRoot) || ...
                    isempty(app.ScanWorkspaceRoot)
                
                error([ ...
                    'scanpretreat has not received the complete RamanCube ' ...
                    'folder configuration.' newline ...
                    'Open scanpretreat through scanspectra.']);
            end
            
            app.Paths = struct();
            
            % =============================================================
            % GLOBAL ROOTS
            % =============================================================
            
            app.Paths.ProjectRoot = ...
                app.ProjectRoot;
            
            app.Paths.WorkspaceRoot = ...
                app.ScanWorkspaceRoot;
            
            app.Paths.RawAcquisitionRoot = ...
                fullfile( ...
                app.ScanInputRoot, ...
                'RAW');
            
            app.Paths.ReadableScanRoot = ...
                fullfile( ...
                app.ScanInputRoot, ...
                'READABLE');
            
            app.Paths.RawRoot = ...
                app.Paths.ReadableScanRoot;
            
            % =============================================================
            % CURRENT WORKSPACE
            % =============================================================
            
            hasScan = ...
                ~isempty(app.CurrentScanName);
            
            if ~hasScan && ...
                    isstruct(app.RamanData) && ...
                    isfield(app.RamanData, 'ScanID')
                
                hasScan = ...
                    strlength(string(app.RamanData.ScanID)) > 0;
            end
            
            if hasScan
                
                scanID = ...
                    app.getCurrentScanID();
                
                workspacePath = ...
                    fullfile( ...
                    app.ScanWorkspaceRoot, ...
                    scanID);
                
                app.Paths.CurrentWorkspace = ...
                    workspacePath;
                
                % ---------------------------------------------------------
                % COSMIC
                % ---------------------------------------------------------
                
                app.Paths.CosmicRoot = ...
                    fullfile( ...
                    workspacePath, ...
                    '01_cosmic');
                
                app.Paths.CosmicDataRoot = ...
                    fullfile( ...
                    app.Paths.CosmicRoot, ...
                    'data');
                
                app.Paths.CosmicEvidenceRoot = ...
                    fullfile( ...
                    app.Paths.CosmicRoot, ...
                    'evidence');
                
                app.Paths.FiguresCosmicRoot = ...
                    fullfile( ...
                    app.Paths.CosmicRoot, ...
                    'figures');
                
                % No second state MAT.
                app.Paths.CosmicStateRoot = ...
                    app.Paths.CosmicDataRoot;
                
                % ---------------------------------------------------------
                % ARTIFACT
                % ---------------------------------------------------------
                
                app.Paths.ArtifactRoot = ...
                    fullfile( ...
                    workspacePath, ...
                    '02_artifact');
                
                app.Paths.ArtifactDataRoot = ...
                    fullfile( ...
                    app.Paths.ArtifactRoot, ...
                    'data');
                
                app.Paths.ArtifactEvidenceRoot = ...
                    fullfile( ...
                    app.Paths.ArtifactRoot, ...
                    'evidence');
                
                app.Paths.FiguresArtifactRoot = ...
                    fullfile( ...
                    app.Paths.ArtifactRoot, ...
                    'figures');
                
                app.Paths.ArtifactStateRoot = ...
                    app.Paths.ArtifactDataRoot;
                
                % ---------------------------------------------------------
                % BACKGROUND
                % ---------------------------------------------------------
                
                app.Paths.BackgroundRoot = ...
                    fullfile( ...
                    workspacePath, ...
                    '03_background');
                
                app.Paths.BackgroundDataRoot = ...
                    fullfile( ...
                    app.Paths.BackgroundRoot, ...
                    'data');
                
                app.Paths.BackgroundEvidenceRoot = ...
                    fullfile( ...
                    app.Paths.BackgroundRoot, ...
                    'evidence');
                
                app.Paths.FiguresBackgroundRoot = ...
                    fullfile( ...
                    app.Paths.BackgroundRoot, ...
                    'figures');
                
                app.Paths.BackgroundStateRoot = ...
                    app.Paths.BackgroundDataRoot;
                
                % ---------------------------------------------------------
                % SINGLE EXTRACT
                % ---------------------------------------------------------
                
                app.Paths.SingleExtractRoot = ...
                    fullfile( ...
                    workspacePath, ...
                    '04_single_extract');
                
                % ---------------------------------------------------------
                % LOGS
                % ---------------------------------------------------------
                
                app.Paths.MetadataRoot = ...
                    fullfile( ...
                    workspacePath, ...
                    'logs');
                
                % Keep analysis-ready inside the same workspace if required.
                app.Paths.AnalysisReadyRoot = ...
                    fullfile( ...
                    workspacePath, ...
                    'analysis_ready');
                
                app.Paths.AnalysisSpectraRoot = ...
                    fullfile( ...
                    app.Paths.AnalysisReadyRoot, ...
                    'spectra');
                
                app.Paths.AnalysisMasksRoot = ...
                    fullfile( ...
                    app.Paths.AnalysisReadyRoot, ...
                    'masks');
                
                app.Paths.AnalysisMapsRoot = ...
                    fullfile( ...
                    app.Paths.AnalysisReadyRoot, ...
                    'maps');
                
            else
                
                app.Paths.CurrentWorkspace = '';
                
                app.Paths.CosmicRoot = '';
                app.Paths.CosmicDataRoot = '';
                app.Paths.CosmicStateRoot = '';
                app.Paths.CosmicEvidenceRoot = '';
                
                app.Paths.ArtifactRoot = '';
                app.Paths.ArtifactDataRoot = '';
                app.Paths.ArtifactStateRoot = '';
                app.Paths.ArtifactEvidenceRoot = '';
                
                app.Paths.BackgroundRoot = '';
                app.Paths.BackgroundDataRoot = '';
                app.Paths.BackgroundStateRoot = '';
                app.Paths.BackgroundEvidenceRoot = '';
                
                app.Paths.SingleExtractRoot = '';
                
                app.Paths.MetadataRoot = '';
                
                app.Paths.AnalysisReadyRoot = '';
                app.Paths.AnalysisSpectraRoot = '';
                app.Paths.AnalysisMasksRoot = '';
                app.Paths.AnalysisMapsRoot = '';
                
                app.Paths.FiguresCosmicRoot = '';
                app.Paths.FiguresArtifactRoot = '';
                app.Paths.FiguresBackgroundRoot = '';
            end
            
            % =============================================================
            % CREATE REQUIRED FOLDERS
            % =============================================================
            
            app.ensureProjectFolders();
            
            % =============================================================
            % BACKWARD-COMPATIBLE ALIASES
            % =============================================================
            
            app.SourceRoot = ...
                app.Paths.RawRoot;
            
            app.DataRoot = ...
                app.Paths.CosmicDataRoot;
            
            app.TraceRoot = ...
                app.Paths.CosmicEvidenceRoot;
            
            app.ImageSaveRoot = ...
                app.Paths.FiguresCosmicRoot;
            
            app.ArtifactDataRoot = ...
                app.Paths.ArtifactDataRoot;
            
            app.ArtifactImageRoot = ...
                app.Paths.FiguresArtifactRoot;
            
            app.BackgroundDataRoot = ...
                app.Paths.BackgroundDataRoot;
            
            app.BackgroundStateRoot = ...
                app.Paths.BackgroundStateRoot;
            
            app.BackgroundImageRoot = ...
                app.Paths.FiguresBackgroundRoot;
            
        end
        
        
        function ensureProjectFolders(app)
            
            % =============================================================
            % GLOBAL FOLDERS
            % =============================================================
            
            folders = { ...
                app.DataInputRoot, ...
                app.DataOutputRoot, ...
                fullfile(app.DataInputRoot, 'single'), ...
                app.ScanInputRoot, ...
                app.ScanOutputRoot, ...
                app.ScanWorkspaceRoot, ...
                app.Paths.RawAcquisitionRoot, ...
                app.Paths.ReadableScanRoot};
            
            % =============================================================
            % CURRENT SCAN WORKSPACE
            % =============================================================
            
            if isfield(app.Paths, 'CurrentWorkspace') && ...
                    ~isempty(app.Paths.CurrentWorkspace)
                
                folders = [ ...
                    folders, ...
                    { ...
                    app.Paths.CurrentWorkspace, ...
                    app.Paths.CosmicRoot, ...
                    app.Paths.CosmicDataRoot, ...
                    app.Paths.CosmicEvidenceRoot, ...
                    app.Paths.FiguresCosmicRoot, ...
                    app.Paths.ArtifactRoot, ...
                    app.Paths.ArtifactDataRoot, ...
                    app.Paths.ArtifactEvidenceRoot, ...
                    app.Paths.FiguresArtifactRoot, ...
                    app.Paths.BackgroundRoot, ...
                    app.Paths.BackgroundDataRoot, ...
                    app.Paths.BackgroundEvidenceRoot, ...
                    app.Paths.FiguresBackgroundRoot, ...
                    app.Paths.SingleExtractRoot, ...
                    app.Paths.MetadataRoot ...
                    }];
            end
            
            % =============================================================
            % CREATE
            % =============================================================
            
            for i = 1:numel(folders)
                
                folderPath = ...
                    folders{i};
                
                if isempty(folderPath)
                    continue
                end
                
                if ~isfolder(folderPath)
                    
                    [ok, messageText] = ...
                        mkdir(folderPath);
                    
                    if ~ok
                        
                        error( ...
                            'Could not create folder:%s%s%s%s', ...
                            newline, ...
                            folderPath, ...
                            newline, ...
                            messageText);
                    end
                end
            end
            
        end
        
        
        function scanID = getCurrentScanID(app)
            
            scanID = '';
            
            if isstruct(app.RamanData) && ...
                    isfield(app.RamanData, 'ScanID') && ...
                    strlength(string(app.RamanData.ScanID)) > 0
                
                scanID = ...
                    char(string(app.RamanData.ScanID));
            end
            
            if isempty(scanID) && ...
                    ~isempty(app.CurrentScanName)
                
                scanID = ...
                    app.CurrentScanName;
            end
            
            if isempty(scanID)
                
                scanID = ...
                    'unknown_scan';
            end
            
            scanID = ...
                app.makeSafeFileName(scanID);
            
        end
        
        
        function workspacePath = getCurrentWorkspacePath(app)
            
            scanID = ...
                app.getCurrentScanID();
            
            if strcmp(scanID, 'unknown_scan')
                
                error( ...
                    'Current scan identity is unavailable.');
            end
            
            workspacePath = ...
                fullfile( ...
                app.ScanWorkspaceRoot, ...
                scanID);
            
            if ~isfolder(workspacePath)
                mkdir(workspacePath);
            end
            
        end
        
        
        function manifestPath = getCurrentManifestPath(app)
            
            manifestPath = ...
                fullfile( ...
                app.getCurrentWorkspacePath(), ...
                'manifest.mat');
            
        end
        
        
        function relativePath = makeProjectRelativePath(app, fullPath)
            
            relativePath = '';
            
            fullPath = ...
                char(string(fullPath));
            
            projectRoot = ...
                char(string(app.ProjectRoot));
            
            if isempty(fullPath) || ...
                    isempty(projectRoot)
                
                return
            end
            
            prefix = ...
                [projectRoot filesep];
            
            if numel(fullPath) >= numel(prefix) && ...
                    strncmpi( ...
                    fullPath, ...
                    prefix, ...
                    numel(prefix))
                
                relativePath = ...
                    fullPath( ...
                    numel(prefix) + 1:end);
            end
            
        end
        
        
        function resolvedPath = resolveStoredPath( ...
                app, ...
                absolutePath, ...
                relativePath)
            
            resolvedPath = '';
            
            absolutePath = ...
                char(string(absolutePath));
            
            relativePath = ...
                char(string(relativePath));
            
            % =============================================================
            % ORIGINAL ABSOLUTE PATH
            % =============================================================
            
            if ~isempty(absolutePath) && ...
                    isfile(absolutePath)
                
                resolvedPath = ...
                    absolutePath;
                
                return
            end
            
            % =============================================================
            % RECONSTRUCT FROM CURRENT PROJECT ROOT
            %
            % This makes a moved RamanCube project portable.
            % =============================================================
            
            if ~isempty(relativePath) && ...
                    ~isempty(app.ProjectRoot)
                
                candidate = ...
                    fullfile( ...
                    app.ProjectRoot, ...
                    relativePath);
                
                if isfile(candidate)
                    
                    resolvedPath = ...
                        candidate;
                    
                    return
                end
            end
            
        end
        
        
        function originalPath = getOriginalSourceMatPath(app)
            
            originalPath = '';
            
            % =============================================================
            % PREFER MANIFEST SOURCE
            % =============================================================
            
            try
                
                manifestPath = ...
                    app.getCurrentManifestPath();
                
                if isfile(manifestPath)
                    
                    T = load( ...
                        manifestPath, ...
                        'Manifest');
                    
                    if isfield(T, 'Manifest') && ...
                            isfield(T.Manifest, 'Source') && ...
                            isfield(T.Manifest.Source, 'MatPath')
                        
                        candidate = ...
                            char(string( ...
                            T.Manifest.Source.MatPath));
                        
                        if isfile(candidate)
                            
                            originalPath = ...
                                candidate;
                            
                            return
                        end
                    end
                end
                
            catch
            end
            
            % =============================================================
            % PAYLOAD SOURCE
            % =============================================================
            
            if isstruct(app.RamanData)
                
                if isfield(app.RamanData, 'OriginalSourceMatPath')
                    
                    candidate = ...
                        char(string( ...
                        app.RamanData.OriginalSourceMatPath));
                    
                    if isfile(candidate)
                        
                        originalPath = ...
                            candidate;
                        
                        return
                    end
                end
                
                if isfield(app.RamanData, 'SourceMatPath')
                    
                    candidate = ...
                        char(string( ...
                        app.RamanData.SourceMatPath));
                    
                    if isfile(candidate)
                        
                        originalPath = ...
                            candidate;
                        
                        return
                    end
                end
            end
            
            % =============================================================
            % CURRENT MAT
            % =============================================================
            
            if ~isempty(app.CurrentMatPath) && ...
                    isfile(app.CurrentMatPath)
                
                originalPath = ...
                    app.CurrentMatPath;
            end
            
        end
        
        
        function spectralDataPath = getCurrentSpectralDataPath(app)
            
            spectralDataPath = '';
            
            % =============================================================
            % PREFER COMPLETED COSMIC CHECKPOINT
            % =============================================================
            
            try
                
                manifestPath = ...
                    app.getCurrentManifestPath();
                
                if isfile(manifestPath)
                    
                    T = ...
                        load( ...
                        manifestPath, ...
                        'Manifest');
                    
                    if isfield(T, 'Manifest') && ...
                            isfield(T.Manifest, 'Stages') && ...
                            isfield(T.Manifest.Stages, 'Cosmic')
                        
                        C = ...
                            T.Manifest.Stages.Cosmic;
                        
                        cosmicUsable = ...
                            isfield(C, 'Completed') && ...
                            logical(C.Completed);
                        
                        if isfield(C, 'Stale')
                            cosmicUsable = ...
                                cosmicUsable && ...
                                ~logical(C.Stale);
                        end
                        
                        if cosmicUsable
                            
                            absolutePath = '';
                            relativePath = '';
                            
                            if isfield(C, 'CheckpointPath')
                                absolutePath = ...
                                    char(string(C.CheckpointPath));
                            end
                            
                            if isfield(C, 'CheckpointRelativePath')
                                relativePath = ...
                                    char(string(C.CheckpointRelativePath));
                            end
                            
                            candidate = ...
                                app.resolveStoredPath( ...
                                absolutePath, ...
                                relativePath);
                            
                            if ~isempty(candidate)
                                
                                spectralDataPath = ...
                                    candidate;
                                
                                return
                            end
                        end
                    end
                end
                
            catch
            end
            
            % =============================================================
            % ORIGINAL SOURCE
            % =============================================================
            
            spectralDataPath = ...
                app.getOriginalSourceMatPath();
            
            if isempty(spectralDataPath)
                
                error( ...
                    ['No materialized Raman spectral cube could be resolved. ' ...
                    'Neither Cosmic checkpoint nor original READABLE source exists.']);
            end
            
        end
        
        
        function updateCurrentManifestStage( ...
                app, ...
                stageName, ...
                checkpointPath, ...
                statePath, ...
                evidenceFolder, ...
                storageMode, ...
                spectralDataPath)
            
            stageName = ...
                char(string(stageName));
            
            switch lower(stageName)
                
                case 'cosmic'
                    stageField = 'Cosmic';
                    stageIndex = 1;
                    
                case 'artifact'
                    stageField = 'Artifact';
                    stageIndex = 2;
                    
                case 'background'
                    stageField = 'Background';
                    stageIndex = 3;
                    
                case {'singleextract', 'single_extract'}
                    stageField = 'SingleExtract';
                    stageIndex = 4;
                    
                otherwise
                    error( ...
                        'Unknown manifest stage: %s', ...
                        stageName);
            end
            
            manifestPath = ...
                app.getCurrentManifestPath();
            
            Manifest = struct();
            
            if isfile(manifestPath)
                
                T = ...
                    load( ...
                    manifestPath, ...
                    'Manifest');
                
                if isfield(T, 'Manifest') && ...
                        isstruct(T.Manifest)
                    
                    Manifest = ...
                        T.Manifest;
                end
            end
            
            if ~isfield(Manifest, 'SchemaVersion')
                Manifest.SchemaVersion = 2;
            end
            
            Manifest.ScanID = ...
                string(app.getCurrentScanID());
            
            Manifest.ScanName = ...
                string(app.CurrentScanName);
            
            Manifest.WorkspacePath = ...
                string(app.getCurrentWorkspacePath());
            
            if ~isfield(Manifest, 'Stages') || ...
                    ~isstruct(Manifest.Stages)
                
                Manifest.Stages = struct();
            end
            
            % =============================================================
            % CURRENT STAGE
            % =============================================================
            
            Stage = struct();
            
            Stage.Available = ...
                true;
            
            Stage.Completed = ...
                true;
            
            Stage.Stale = ...
                false;
            
            Stage.CheckpointPath = ...
                string(checkpointPath);
            
            Stage.CheckpointRelativePath = ...
                string( ...
                app.makeProjectRelativePath( ...
                checkpointPath));
            
            Stage.StatePath = ...
                string(statePath);
            
            Stage.StateRelativePath = ...
                string( ...
                app.makeProjectRelativePath( ...
                statePath));
            
            Stage.EvidenceFolder = ...
                string(evidenceFolder);
            
            Stage.StorageMode = ...
                string(storageMode);
            
            Stage.SpectralDataPath = ...
                string(spectralDataPath);
            
            Stage.SpectralDataRelativePath = ...
                string( ...
                app.makeProjectRelativePath( ...
                spectralDataPath));
            
            Stage.CompletedAt = ...
                datetime('now');
            
            Manifest.Stages.(stageField) = ...
                Stage;
            
            % =============================================================
            % DOWNSTREAM INVALIDATION
            % =============================================================
            
            stageOrder = { ...
                'Cosmic', ...
                'Artifact', ...
                'Background', ...
                'SingleExtract'};
            
            for i = stageIndex + 1:numel(stageOrder)
                
                downstreamName = ...
                    stageOrder{i};
                
                if isfield(Manifest.Stages, downstreamName)
                    
                    Manifest.Stages.(downstreamName).Completed = ...
                        false;
                    
                    Manifest.Stages.(downstreamName).Stale = ...
                        true;
                end
            end
            
            Manifest.LastCompletedStage = ...
                string(stageField);
            
            Manifest.LastOpenedStage = ...
                string(stageField);
            
            Manifest.LastModified = ...
                datetime('now');
            
            % =============================================================
            % ATOMIC MANIFEST SAVE
            % =============================================================
            
            tempPath = ...
                [manifestPath '.tmp.mat'];
            
            save( ...
                tempPath, ...
                'Manifest', ...
                '-v7');
            
            if isfile(manifestPath)
                delete(manifestPath);
            end
            
            [ok, messageText] = ...
                movefile( ...
                tempPath, ...
                manifestPath, ...
                'f');
            
            if ~ok
                
                error( ...
                    'Could not commit manifest:%s%s', ...
                    newline, ...
                    messageText);
            end
            
        end
        
        function scanList = collectScanList(app,rootFolder)
            scanList = struct('Name',{},'MatPath',{},'Folder',{});
            folderInfo = dir(rootFolder);
            count = 0;
            for i = 1:numel(folderInfo)
                if ~folderInfo(i).isdir || strcmp(folderInfo(i).name,'.') || strcmp(folderInfo(i).name,'..')
                    continue
                end
                scanFolder = fullfile(rootFolder,folderInfo(i).name);
                % Prefer the readable scan generated by Loadscan.
                preferredFiles = ...
                    dir(fullfile(scanFolder, '*_RamanData.mat'));
                
                if numel(preferredFiles) == 1
                    
                    selectedMatFile = preferredFiles(1);
                    
                elseif numel(preferredFiles) > 1
                    
                    warning( ...
                        'RamanCube:MultipleReadableMATFiles', ...
                        ['More than one *_RamanData.mat file was found in:\n%s\n' ...
                        'The folder will be skipped to prevent loading the wrong scan.'], ...
                        scanFolder);
                    
                    continue
                    
                else
                    
                    matFiles = ...
                        dir(fullfile(scanFolder, '*.mat'));
                    
                    if numel(matFiles) ~= 1
                        % Do not guess when zero or multiple generic MAT files exist.
                        continue
                    end
                    
                    selectedMatFile = ...
                        matFiles(1);
                end
                
                count = ...
                    count + 1;
                
                scanList(count).Name = ...
                    folderInfo(i).name;
                
                scanList(count).MatPath = ...
                    fullfile(scanFolder, selectedMatFile.name);
                
                scanList(count).Folder = ...
                    scanFolder;
            end
            if isempty(scanList)
                matFiles = dir(fullfile(rootFolder,'*.mat'));
                [~,ord] = sort({matFiles.name});
                matFiles = matFiles(ord);
                for i = 1:numel(matFiles)
                    count = count+1;
                    [~,nm] = fileparts(matFiles(i).name);
                    scanList(count).Name = nm;
                    scanList(count).MatPath = fullfile(rootFolder,matFiles(i).name);
                    scanList(count).Folder = rootFolder;
                end
            end
        end
        
        function populateScanTree(app)
            app.IsUpdatingTree = true;
            delete(app.Tree.Children);
            root = uitreenode(app.Tree,'Text',sprintf('Scans (%d)',numel(app.ScanList)),'NodeData',struct('Type','root'));
            for i = 1:numel(app.ScanList)
                uitreenode(root,'Text',app.ScanList(i).Name,'NodeData',struct('Type','scan','Index',i));
            end
            expand(root);
            app.IsUpdatingTree = false;
        end


        function populateCandidateTree(app, keepCurrent)
            
            if nargin < 2
                keepCurrent = false;
            end
            
            app.IsUpdatingTree = ...
                true;
            
            try
                delete(app.Tree.Children);
            catch
            end
            
            rootText = ...
                app.CurrentScanName;
            
            if isempty(rootText)
                rootText = ...
                    'Current scan';
            end
            
            root = ...
                uitreenode( ...
                app.Tree, ...
                'Text', ...
                rootText, ...
                'NodeData', ...
                struct( ...
                'Type', 'scan', ...
                'Index', app.CurrentScanIndex));
            
            if isempty(app.CandidateTable) || ...
                    height(app.CandidateTable) == 0
                
                uitreenode( ...
                    root, ...
                    'Text', ...
                    'No cosmic candidate', ...
                    'NodeData', ...
                    struct('Type', 'none'));
                
                expand(root);
                
                app.IsUpdatingTree = ...
                    false;
                
                return
            end
            
            % Ensure fields used by the tree exist.
            app.CandidateTable = ...
                app.ensureCosmicCandidateTableSchema( ...
                app.CandidateTable);
            
            app.CandidateSummary = ...
                app.computeCandidateSummary();
            
            S = ...
                app.CandidateSummary;
            
            uitreenode( ...
                root, ...
                'Text', ...
                sprintf( ...
                ['Summary | total=%d | accepted=%d | ignored=%d | ' ...
                'pending=%d | corrected=%d'], ...
                S.Total, ...
                S.Accepted, ...
                S.Ignored, ...
                S.Pending, ...
                S.Corrected), ...
                'NodeData', ...
                struct('Type', 'summary'));
            
            displayIdx = ...
                app.getCandidateTreeDisplayIndices();
            
            displayIdx = ...
                displayIdx(:);
            
            if isempty(displayIdx)
                
                uitreenode( ...
                    root, ...
                    'Text', ...
                    'No candidate is available in the current review state.', ...
                    'NodeData', ...
                    struct('Type', 'none'));
                
                expand(root);
                
                app.IsUpdatingTree = ...
                    false;
                
                return
            end
            
            sourceValues = ...
                string(app.CandidateTable.Source(displayIdx));
            
            automaticDisplayIdx = ...
                displayIdx(~strcmpi(sourceValues, "Manual"));
            
            manualDisplayIdx = ...
                displayIdx(strcmpi(sourceValues, "Manual"));
            
            % =============================================================
            % AUTOMATIC PAGES
            % =============================================================
            
            nAutomatic = ...
                numel(automaticDisplayIdx);
            
            automaticPageSize = ...
                max(1, round(app.CandidatePageSize));
            
            automaticNumPages = ...
                max(1, ceil(nAutomatic / automaticPageSize));
            
            app.CurrentCandidatePage = ...
                max( ...
                1, ...
                min( ...
                app.CurrentCandidatePage, ...
                automaticNumPages));
            
            app.CandidateNumPages = ...
                automaticNumPages;
            
            automaticPagesRoot = ...
                uitreenode( ...
                root, ...
                'Text', ...
                sprintf( ...
                'Automatic pages | candidates=%d | page size=%d | pages=%d', ...
                nAutomatic, ...
                automaticPageSize, ...
                automaticNumPages), ...
                'NodeData', ...
                struct('Type', 'pages'));
            
            if nAutomatic == 0
                
                uitreenode( ...
                    automaticPagesRoot, ...
                    'Text', ...
                    'No automatic candidate', ...
                    'NodeData', ...
                    struct('Type', 'none'));
                
                automaticVisible = ...
                    [];
                
            else
                
                for p = 1:automaticNumPages
                    
                    i1 = ...
                        (p - 1) * automaticPageSize + 1;
                    
                    i2 = ...
                        min( ...
                        nAutomatic, ...
                        p * automaticPageSize);
                    
                    if p == app.CurrentCandidatePage
                        
                        pageText = ...
                            sprintf( ...
                            '> Page %d | automatic candidates %d-%d', ...
                            p, i1, i2);
                        
                    else
                        
                        pageText = ...
                            sprintf( ...
                            'Page %d | automatic candidates %d-%d', ...
                            p, i1, i2);
                    end
                    
                    uitreenode( ...
                        automaticPagesRoot, ...
                        'Text', ...
                        pageText, ...
                        'NodeData', ...
                        struct( ...
                        'Type', 'page', ...
                        'Page', p));
                end
                
                automaticVisible = ...
                    app.getAutomaticCandidatePageIndices( ...
                    app.CurrentCandidatePage);
            end
            
            automaticCurrentRoot = ...
                uitreenode( ...
                root, ...
                'Text', ...
                sprintf( ...
                'Automatic current page %d | candidates=%d', ...
                app.CurrentCandidatePage, ...
                numel(automaticVisible)), ...
                'NodeData', ...
                struct( ...
                'Type', 'current_page', ...
                'Page', app.CurrentCandidatePage));
            
            for n = 1:numel(automaticVisible)
                
                app.createCandidateTreeNode( ...
                    automaticCurrentRoot, ...
                    automaticVisible(n));
            end
            
            % =============================================================
            % MANUAL CANDIDATES
            %
            % Manual events are placed in one separate list independent of
            % automatic paging.
            % =============================================================
            
            manualRoot = ...
                uitreenode( ...
                root, ...
                'Text', ...
                sprintf( ...
                'Manual candidates | total=%d', ...
                numel(manualDisplayIdx)), ...
                'NodeData', ...
                struct('Type', 'manual_group'));
            
            if isempty(manualDisplayIdx)
                
                uitreenode( ...
                    manualRoot, ...
                    'Text', ...
                    'No manual candidate', ...
                    'NodeData', ...
                    struct('Type', 'none'));
                
            else
                
                for n = 1:numel(manualDisplayIdx)
                    
                    app.createCandidateTreeNode( ...
                        manualRoot, ...
                        manualDisplayIdx(n));
                end
            end
            
            app.CandidateVisibleIndices = ...
                unique( ...
                [automaticVisible(:); manualDisplayIdx(:)], ...
                'stable');
            
            expand(root);
            expand(automaticPagesRoot);
            expand(automaticCurrentRoot);
            expand(manualRoot);
            
            if keepCurrent && ...
                    ~isnan(app.CurrentCandidateIndex) && ...
                    app.CurrentCandidateIndex >= 1 && ...
                    app.CurrentCandidateIndex <= height(app.CandidateTable)
                
                app.selectCandidateNode( ...
                    app.CurrentCandidateIndex);
            end
            app.IsUpdatingTree = ...
                false;
            
        end
        
        
        function selectCandidateNode(app,idx)
            allNodes = app.findTreeCandidateNodes(app.Tree.Children);
            for i = 1:numel(allNodes)
                nd = allNodes(i);
                if isstruct(nd.NodeData) && isfield(nd.NodeData,'Type') && strcmp(nd.NodeData.Type,'candidate') && nd.NodeData.Index == idx
                    app.Tree.SelectedNodes = nd;
                    return
                end
            end
        end
        
        
        function T = ensureCosmicCandidateTableSchema(app, T)
            
            if isempty(T)
                
                T = ...
                    app.makeEmptyCosmicCandidateTable();
                
                return
            end
            
            n = ...
                height(T);
            
            defaults = struct();
            
            defaults.ID = ...
                (1:n)';
            
            defaults.Y = ...
                zeros(n, 1);
            
            defaults.X = ...
                zeros(n, 1);
            
            defaults.KSeedStart = ...
                zeros(n, 1);
            
            defaults.KSeedEnd = ...
                zeros(n, 1);
            
            defaults.KStart = ...
                zeros(n, 1);
            
            defaults.KPeak = ...
                zeros(n, 1);
            
            defaults.KPeak2 = ...
                nan(n, 1);
            
            defaults.KEnd = ...
                zeros(n, 1);
            
            defaults.WN = ...
                zeros(n, 1);
            
            defaults.WN2 = ...
                nan(n, 1);
            
            defaults.EventType = ...
                repmat("Single", n, 1);
            
            defaults.FWHM_cm1 = ...
                zeros(n, 1);
            
            defaults.SupportWidth_cm1 = ...
                zeros(n, 1);
            
            defaults.ResolutionRatio = ...
                zeros(n, 1);
            
            defaults.DerivativeZ = ...
                zeros(n, 1);
            
            defaults.ResidualZ = ...
                zeros(n, 1);
            
            defaults.NeighborDiscordance = ...
                nan(n, 1);
            
            defaults.Score = ...
                zeros(n, 1);
            
            defaults.Source = ...
                repmat("Automatic", n, 1);
            
            defaults.Accepted = ...
                false(n, 1);
            
            defaults.Reviewed = ...
                false(n, 1);
            
            defaults.Corrected = ...
                false(n, 1);
            
            requiredNames = ...
                fieldnames(defaults);
            
            currentNames = ...
                T.Properties.VariableNames;
            
            for k = 1:numel(requiredNames)
                
                variableName = ...
                    requiredNames{k};
                
                if ~ismember(variableName, currentNames)
                    
                    T.(variableName) = ...
                        defaults.(variableName);
                end
            end
            
            % Normalize important types.
            T.EventType = ...
                string(T.EventType);
            
            T.Source = ...
                string(T.Source);
            
            T.Accepted = ...
                logical(T.Accepted);
            
            T.Reviewed = ...
                logical(T.Reviewed);
            
            T.Corrected = ...
                logical(T.Corrected);
            
            T.ID = ...
                (1:height(T))';
        end
        
        function nodes = findTreeCandidateNodes(app,nodeList)
            nodes = matlab.ui.container.TreeNode.empty;
            for i = 1:numel(nodeList)
                nd = nodeList(i);
                if isstruct(nd.NodeData) && isfield(nd.NodeData,'Type') && strcmp(nd.NodeData.Type,'candidate')
                    nodes(end+1) = nd;
                end
                if ~isempty(nd.Children)
                    nodes = [nodes app.findTreeCandidateNodes(nd.Children)];
                end
            end
        end
        
        function txt = makeCandidateNodeText(app, i)
            
            status = ...
                app.getCandidateStatus(i);
            
            correctedText = ...
                "";
            
            if app.CandidateTable.Corrected(i)
                correctedText = ...
                    " | Corrected";
            end
            
            sourceText = ...
                string(app.CandidateTable.Source(i));
            
            typeText = ...
                string(app.CandidateTable.EventType(i));
            
            txt = sprintf( ...
                '%03d | %s | %s | Y=%d X=%d | %.1f cm^{-1} | %.2fR | %s%s', ...
                i, ...
                status, ...
                char(typeText), ...
                app.CandidateTable.Y(i), ...
                app.CandidateTable.X(i), ...
                app.CandidateTable.WN(i), ...
                app.CandidateTable.ResolutionRatio(i), ...
                char(sourceText), ...
                char(correctedText));
        end


        function idx = getDecisionTargetIndices(app)
            
            idx = [];
            
            if isempty(app.CandidateTable)
                return
            end
            
            app.CandidateTable = ...
                app.ensureCosmicCandidateTableSchema( ...
                app.CandidateTable);
            
            selectedNodes = ...
                app.Tree.SelectedNodes;
            
            if isempty(selectedNodes)
                return
            end
            
            candidateIdx = [];
            selectedPage = [];
            
            for n = 1:numel(selectedNodes)
                
                nodeData = ...
                    selectedNodes(n).NodeData;
                
                if isempty(nodeData) || ...
                        ~isstruct(nodeData) || ...
                        ~isfield(nodeData, 'Type')
                    
                    continue
                end
                
                nodeType = ...
                    char(string(nodeData.Type));
                
                if strcmp(nodeType, 'candidate')
                    
                    if isfield(nodeData, 'Index')
                        
                        candidateIdx(end + 1, 1) = ...
                            double(nodeData.Index); %#ok<AGROW>
                    end
                    
                elseif strcmp(nodeType, 'page') || ...
                        strcmp(nodeType, 'current_page')
                    
                    if isfield(nodeData, 'Page')
                        
                        selectedPage(end + 1, 1) = ...
                            double(nodeData.Page); %#ok<AGROW>
                    end
                end
            end
            
            % Explicit candidate rows take priority.
            if ~isempty(candidateIdx)
                
                idx = ...
                    candidateIdx(:);
                
            elseif ~isempty(selectedPage)
                
                pageNumber = ...
                    round(selectedPage(end));
                
                idx = ...
                    app.getAutomaticCandidatePageIndices( ...
                    pageNumber);
                
                app.CurrentCandidatePage = ...
                    pageNumber;
            end
            
            idx = ...
                double(idx(:));
            
            idx = ...
                idx(isfinite(idx));
            
            idx = ...
                round(idx);
            
            idx = ...
                idx( ...
                idx >= 1 & ...
                idx <= height(app.CandidateTable));
            
            idx = ...
                unique(idx, 'stable');
        end
        
        
        function applyCosmicDecision(app, acceptValue)
            
            % =============================================================
            % VALIDATION
            % =============================================================
            
            if isempty(app.CandidateTable)
                
                uialert( ...
                    app.UIFigure, ...
                    'Run Cosmic detection first.', ...
                    'No cosmic candidates');
                
                return
            end
            
            if app.CosmicReviewFinalized
                
                uialert( ...
                    app.UIFigure, ...
                    'The final cosmic list has already been confirmed.', ...
                    'Cosmic list finalized');
                
                return
            end
            
            % Read selection before rebuilding the tree.
            selectedNodes = ...
                app.Tree.SelectedNodes;
            
            selectedPage = [];
            selectedCandidateIdx = [];
            
            for n = 1:numel(selectedNodes)
                
                nodeData = ...
                    selectedNodes(n).NodeData;
                
                if isempty(nodeData) || ...
                        ~isstruct(nodeData) || ...
                        ~isfield(nodeData, 'Type')
                    
                    continue
                end
                
                nodeType = ...
                    char(string(nodeData.Type));
                
                if strcmp(nodeType, 'candidate') && ...
                        isfield(nodeData, 'Index')
                    
                    selectedCandidateIdx(end + 1, 1) = ...
                        double(nodeData.Index); %#ok<AGROW>
                    
                elseif any(strcmp(nodeType, {'page', 'current_page'}))
                    
                    if isfield(nodeData, 'Page')
                        
                        selectedPage(end + 1, 1) = ...
                            double(nodeData.Page); %#ok<AGROW>
                        
                    else
                        
                        selectedPage(end + 1, 1) = ...
                            app.CurrentCandidatePage; %#ok<AGROW>
                    end
                end
            end
            
            % =============================================================
            % RESOLVE DECISION TARGET
            % =============================================================
            
            idx = ...
                app.getDecisionTargetIndices();
            
            if isempty(idx)
                
                uialert( ...
                    app.UIFigure, ...
                    [ ...
                    'Select one or more candidate rows, or select a page row.' ...
                    newline ...
                    'Scan, Summary and Pages root nodes cannot be accepted or ignored.'], ...
                    'Select candidate or page');
                
                return
            end
            
            % =============================================================
            % OPTIONAL PAGE CONFIRMATION
            % =============================================================
            
            isPageDecision = ...
                isempty(selectedCandidateIdx) && ...
                (~isempty(selectedPage) || ...
                isempty(selectedNodes));
            
            if isPageDecision
                
                pageNumber = ...
                    app.CurrentCandidatePage;
                
                if ~isempty(selectedPage)
                    pageNumber = ...
                        selectedPage(end);
                end
                
                if acceptValue
                    actionText = ...
                        'Agree';
                else
                    actionText = ...
                        'Ignore';
                end
                
                choice = ...
                    uiconfirm( ...
                    app.UIFigure, ...
                    { ...
                    sprintf('%s all %d candidates in Page %d?', ...
                    actionText, ...
                    numel(idx), ...
                    pageNumber); ...
                    ''; ...
                    'Only candidates in this page will be changed.'; ...
                    'Candidates on other pages remain unchanged.'}, ...
                    [actionText ' page'], ...
                    'Options', {'Yes', 'No'}, ...
                    'DefaultOption', 'No', ...
                    'CancelOption', 'No');
                
                if ~strcmp(choice, 'Yes')
                    return
                end
            end
            
            % =============================================================
            % APPLY DECISION
            % =============================================================
            
            app.CandidateTable.Accepted(idx) = ...
                logical(acceptValue);
            
            app.CandidateTable.Reviewed(idx) = ...
                true;
            
            app.CandidateSummary = ...
                app.computeCandidateSummary();
            
            if ~isempty(selectedPage)
                
                app.CurrentCandidatePage = ...
                    max( ...
                    1, ...
                    min( ...
                    app.CandidateNumPages, ...
                    round(selectedPage(end))));
            end
            
            automaticVisible = ...
                app.getAutomaticCandidatePageIndices( ...
                app.CurrentCandidatePage);
            
            manualVisible = ...
                find( ...
                strcmpi( ...
                string(app.CandidateTable.Source), ...
                "Manual"));
            
            app.CandidateVisibleIndices = ...
                unique( ...
                [automaticVisible(:); manualVisible(:)], ...
                'stable');
            
            % =============================================================
            % REBUILD TREE AFTER DECISION
            % =============================================================
            
            app.populateCandidateTree(false);
            
            % For one candidate, display that candidate.
            if numel(idx) == 1
                
                app.CurrentCandidateIndex = ...
                    idx(1);
                
                app.selectCandidateNode( ...
                    idx(1));
                
                app.displayCandidate( ...
                    idx(1));
                
            else
                
                % For multi-candidate or page decision, show page summary.
                app.CurrentCandidateIndex = ...
                    NaN;
                
                app.plotCandidateSummaryView();
            end
            
            % =============================================================
            % STATUS TEXT
            % =============================================================
            
            if acceptValue
                decisionText = ...
                    'agreed';
            else
                decisionText = ...
                    'ignored';
            end
            
            app.setCosmicWorkflowControls('reviewing');
            
            app.setText( ...
                app.makeCosmicWorkflowStatusText( ...
                'Cosmic review decision', ...
                { ...
                sprintf('Candidates %s: %d', ...
                decisionText, ...
                numel(idx)); ...
                sprintf('Current automatic page: %d / %d', ...
                app.CurrentCandidatePage, ...
                app.CandidateNumPages); ...
                ''; ...
                sprintf('Accepted: %d', ...
                nnz(app.CandidateTable.Accepted)); ...
                sprintf('Ignored: %d', ...
                nnz( ...
                app.CandidateTable.Reviewed & ...
                ~app.CandidateTable.Accepted)); ...
                sprintf('Pending: %d', ...
                nnz(~app.CandidateTable.Reviewed))}));
        end
        
        function status = getCandidateStatus(app,i)
            if ~app.CandidateTable.Reviewed(i)
                status = 'New';
            elseif app.CandidateTable.Accepted(i)
                status = 'Agree';
            else
                status = 'Ignore';
            end
        end
        
        function idxs = getSelectedCandidateIndices(app)
            idxs = [];
            nodes = app.Tree.SelectedNodes;
            for i = 1:numel(nodes)
                nd = nodes(i);
                if isempty(nd.NodeData) || ~isstruct(nd.NodeData) || ~isfield(nd.NodeData,'Type')
                    continue
                end
                if strcmp(nd.NodeData.Type,'candidate')
                    idxs(end+1) = nd.NodeData.Index;
                end
            end
            idxs = unique(idxs);
        end
        

        function loadScanByIndex(app,idx)
            
            if idx < 1 || idx > numel(app.ScanList)
                error('Invalid scan index.');
            end
            
            app.CurrentScanIndex = idx;
            app.CurrentScanName = app.ScanList(idx).Name;
            app.CurrentMatPath = app.ScanList(idx).MatPath;
            
            S = load(app.CurrentMatPath);
            R = app.extractRamanStruct(S);
            
            if ~isfield(R,'Cube')
                error('The selected MAT file does not contain field Cube.');
            end
            
            app.RamanData = R;
            app.CubeOriginal = double(R.Cube);
            app.CorrectedCube = [];
            
            if isfield(R,'Wavenumber')
                app.Wavenumber = double(R.Wavenumber(:));
            else
                app.Wavenumber = (1:size(app.CubeOriginal,3)).';
            end
            
            % Reset cosmic state.
            app.TempMask = logical.empty;
            app.TempScore = single.empty;
            app.ConfirmedMask = logical.empty;
            app.CandidateTable = table();
            app.CurrentCandidateIndex = NaN;
            
            app.CosmicConfirmed = false;
            app.CorrectionTrialMode = false;
            app.CorrectionReady = false;
            app.CorrectParamsChecked = false;
            
            app.LastDetectionParamsAfterCorrection = struct();
            app.LastDetectionParamSignature = '';
            
            app.BackgroundMeanSpectrum = [];
            app.BackgroundStdSpectrum = [];
            app.BackgroundSampleLinearIdx = [];
            
            app.CosmicWNMode = 'Full wavenumber range';
            app.CosmicWNMask = true(numel(app.Wavenumber),1);
            app.CosmicWNIncludeRanges = [];
            app.CosmicWNExcludeRanges = [];
            
            app.CandidatePageSize = 200;
            app.CurrentCandidatePage = 1;
            app.CandidateNumPages = 1;
            app.CandidateVisibleIndices = [];
            app.CandidateSummary = struct();
            
            % Reset artifact state.
            app.ArtifactReady = false;
            app.ArtifactConfirmed = false;
            app.ArtifactMask2D = logical.empty;
            app.ValidPixelMask2D = logical.empty;
            app.ArtifactDisplayMapRaw = [];
            app.ArtifactDisplayMapClean = [];
            app.ArtifactDisplayLabel = '';
            app.ArtifactTable = table();
            
            if isempty(app.ROIDataMap) || ~isa(app.ROIDataMap,'containers.Map')
                app.ROIDataMap = containers.Map('KeyType','char','ValueType','any');
            end
            
            if isKey(app.ROIDataMap,app.CurrentScanName)
                app.CurrentROIMask = app.ROIDataMap(app.CurrentScanName);
                app.ROIReady = true;
            else
                app.CurrentROIMask = true(size(app.CubeOriginal,1),size(app.CubeOriginal,2));
                app.ROIReady = false;
            end
            
            app.setText({...
                'SCAN LOADED';...
                '================================';...
                '';...
                ['Scan: ' app.CurrentScanName];...
                ['MAT file: ' app.CurrentMatPath];...
                sprintf('Cube size: %d x %d x %d', ...
                size(app.CubeOriginal,1), ...
                size(app.CubeOriginal,2), ...
                size(app.CubeOriginal,3));...
                '';...
                'ROI step:';...
                'Draw ROI if you only want to detect cosmic spikes inside selected cells/regions.'});
            
            app.plotRawMap();
            
            % Send a newly selected raw scan to scanspectra.
            app.sendActiveLoadedScanToMainApp( ...
                'Start new from raw');
        end
        
        function R = extractRamanStruct(app, S)
            
            % Latest pretreatment stage has highest priority.
            if isfield(S, 'RamanDataBackground')
                
                R = S.RamanDataBackground;
                return
            end
            
            if isfield(S, 'RamanDataArtifact')
                
                R = S.RamanDataArtifact;
                return
            end
            
            if isfield(S, 'RamanDataCosmic')
                
                R = S.RamanDataCosmic;
                return
            end
            
            if isfield(S, 'RamanData')
                
                R = S.RamanData;
                return
            end
            
            names = fieldnames(S);
            
            for i = 1:numel(names)
                
                value = S.(names{i});
                
                if isstruct(value) && ...
                        isfield(value, 'Cube')
                    
                    R = value;
                    return
                end
            end
            
            error( ...
                ['No RamanData-like structure containing field Cube ' ...
                'was found.']);
        end
        
        function ensureROIForCurrentScan(app)
            if isempty(app.CubeOriginal)
                return
            end
            if app.ROIReady
                choice = uiconfirm(app.UIFigure,...
                    'ROI is already available for this scan. Use existing ROI or redraw?',...
                    'ROI selection',...
                    'Options',{'Use existing','Redraw ROI','Use full scan'},...
                    'DefaultOption','Use existing',...
                    'CancelOption','Use existing');
                if strcmp(choice,'Use existing')
                    return
                elseif strcmp(choice,'Use full scan')
                    app.CurrentROIMask = true(size(app.CubeOriginal,1),size(app.CubeOriginal,2));
                    app.ROIReady = false;
                    return
                end
            else
                choice = uiconfirm(app.UIFigure,...
                    'Draw ROI for this scan before cosmic detection?',...
                    'ROI selection',...
                    'Options',{'Draw ROI','Use full scan'},...
                    'DefaultOption','Draw ROI',...
                    'CancelOption','Use full scan');
                if strcmp(choice,'Use full scan')
                    app.CurrentROIMask = true(size(app.CubeOriginal,1),size(app.CubeOriginal,2));
                    app.ROIReady = false;
                    return
                end
            end
            app.drawMultiROI();
        end
        
        function drawMultiROI(app)
            if isempty(app.CubeOriginal)
                return
            end
            [mapImg,mapLabel] = app.buildRamanMap(app.getActiveCube());
            roiMask = false(size(mapImg));
            fig = figure('Color','w','Name',['ROI drawing: ' app.CurrentScanName],...
                'NumberTitle','off','Position',[100 80 950 820]);
            ax = axes(fig);
            app.showMapOnNormalAxes(ax,mapImg);
            title(ax,{['ROI drawing: ' app.CurrentScanName],mapLabel,...
                'Draw polygon, double-click once to finish current ROI'},'Interpreter','none');
            hold(ax,'on');
            keepDrawing = true;
            while keepDrawing && isvalid(fig)
                roi = drawpolygon(ax,'Color','y','LineWidth',1.8);
                wait(roi);
                if ~isvalid(fig)
                    break
                end
                thisMask = createMask(roi);
                tempMask = roiMask | thisMask;
                delete(roi);
                cla(ax);
                app.showMapOnNormalAxes(ax,mapImg);
                title(ax,{['ROI drawing: ' app.CurrentScanName],mapLabel,...
                    'Yellow contour = current accumulated ROI'},'Interpreter','none');
                hold(ax,'on');
                if any(tempMask(:))
                    contour(ax,tempMask,[0.5 0.5],'y','LineWidth',1.5);
                end
                drawnow;
                choice = questdlg('What do you want to do with this ROI?',...
                    'ROI drawing',...
                    'Re-draw','Add more','Finish','Finish');
                if isempty(choice)
                    choice = 'Finish';
                end
                if strcmp(choice,'Re-draw')
                    cla(ax);
                    app.showMapOnNormalAxes(ax,mapImg);
                    title(ax,{['ROI drawing: ' app.CurrentScanName],mapLabel,...
                        'Re-draw current ROI'},'Interpreter','none');
                    hold(ax,'on');
                    if any(roiMask(:))
                        contour(ax,roiMask,[0.5 0.5],'y','LineWidth',1.5);
                    end
                    continue
                elseif strcmp(choice,'Add more')
                    roiMask = tempMask;
                    cla(ax);
                    app.showMapOnNormalAxes(ax,mapImg);
                    title(ax,{['ROI drawing: ' app.CurrentScanName],mapLabel,...
                        'Draw another ROI'},'Interpreter','none');
                    hold(ax,'on');
                    contour(ax,roiMask,[0.5 0.5],'y','LineWidth',1.5);
                    continue
                else
                    roiMask = tempMask;
                    keepDrawing = false;
                end
            end
            if isvalid(fig)
                close(fig);
            end
            if ~any(roiMask(:))
                app.CurrentROIMask = true(size(app.CubeOriginal,1),size(app.CubeOriginal,2));
                app.ROIReady = false;
                app.setText({'ROI selection finished.';'No ROI was drawn. Full scan will be used.'});
            else
                app.CurrentROIMask = roiMask;
                app.ROIReady = true;
                app.ROIDataMap(app.CurrentScanName) = roiMask;
                app.setText({...
                    'ROI selection finished.';...
                    sprintf('ROI pixels: %d / %d = %.2f%%',nnz(roiMask),numel(roiMask),100*nnz(roiMask)/numel(roiMask));...
                    '';...
                    'Next step: run Cosmic detection.'});
            end
            app.plotRawMap();
        end
        
        function [mapImg,mapLabel] = buildRamanMap(app,cube)
            wn = app.Wavenumber;
            useBand = wn >= 2840 & wn <= 2940;
            if nnz(useBand) >= 3
                mapImg = mean(cube(:,:,useBand),3,'omitnan');
                mapLabel = 'Mean intensity 2840-2940 cm^{-1}';
            else
                mapImg = max(cube,[],3);
                mapLabel = 'Maximum intensity projection';
            end
            mapImg = double(mapImg);
        end
        
        function plotRawMap(app)
            
            if isempty(app.CubeOriginal)
                return
            end
            
            [mapImg, mapLabel] = ...
                app.buildRamanMap(app.getActiveCube());
            
            ax = ...
                app.UIAxes;
            
            app.resetImageAxis(ax);
            
            app.showMapOnNormalAxes( ...
                ax, ...
                mapImg);
            
            % Match Loadscan display.
            colormap(ax, 'hot');
            
            % Prevent a few extreme pixels from destroying map contrast.
            app.applyRobustCLim( ...
                ax, ...
                mapImg);
            
            title( ...
                ax, ...
                {app.CurrentScanName, mapLabel}, ...
                'Interpreter', ...
                'none');
            
            hold(ax, 'on');
            
            if ~isempty(app.CurrentROIMask) && ...
                    any(app.CurrentROIMask(:)) && ...
                    app.ROIReady
                
                contour( ...
                    ax, ...
                    app.CurrentROIMask, ...
                    [0.5 0.5], ...
                    'y', ...
                    'LineWidth', ...
                    1.2);
            end
            
            hold(ax, 'off');
            
            app.resetSpectrumAxis( ...
                app.UIAxes2);
            
            app.fixAxisMargin( ...
                ax, ...
                false);
            
            app.fixAxisMargin( ...
                app.UIAxes2, ...
                true);
            
            title( ...
                app.UIAxes2, ...
                'Axis 2');
        end
        
        function clearAxes(app)
            app.resetSpectrumAxis(app.UIAxes);
            app.resetSpectrumAxis(app.UIAxes2);
            title(app.UIAxes,'Axis 1');
            title(app.UIAxes2,'Axis 2');
        end


        function addCosmicWNFocusOverlay(app, ax)
            
            if isempty(app.Wavenumber) || ...
                    isempty(ax) || ...
                    ~isvalid(ax)
                
                return
            end
            
            wn = ...
                double(app.Wavenumber(:));
            
            nWn = ...
                numel(wn);
            
            if ~isempty(app.CosmicWNMask) && ...
                    numel(app.CosmicWNMask) == nWn
                
                focusMask = ...
                    logical(app.CosmicWNMask(:));
                
            else
                
                focusMask = ...
                    true(nWn, 1);
            end
            
            delete( ...
                findall( ...
                ax, ...
                'Tag', ...
                'CosmicWNFocusOverlay'));
            
            originalHold = ...
                ishold(ax);
            
            hold(ax, 'on');
            
            yl = ...
                ylim(ax);
            
            % =============================================================
            % SHADE EXCLUDED CONTIGUOUS REGIONS
            % =============================================================
            
            excludedMask = ...
                ~focusMask;
            
            padded = ...
                [false; excludedMask; false];
            
            starts = ...
                find(diff(padded) == 1);
            
            ends = ...
                find(diff(padded) == -1) - 1;
            
            for r = 1:numel(starts)
                
                k1 = ...
                    starts(r);
                
                k2 = ...
                    ends(r);
                
                x1 = ...
                    wn(k1);
                
                x2 = ...
                    wn(k2);
                
                patch( ...
                    ax, ...
                    [x1 x2 x2 x1], ...
                    [yl(1) yl(1) yl(2) yl(2)], ...
                    [0.75 0.75 0.75], ...
                    'FaceAlpha', ...
                    0.18, ...
                    'EdgeColor', ...
                    'none', ...
                    'HitTest', ...
                    'off', ...
                    'Tag', ...
                    'CosmicWNFocusOverlay');
                
                xline( ...
                    ax, ...
                    x1, ...
                    ':', ...
                    'Color', ...
                    [0.35 0.35 0.35], ...
                    'LineWidth', ...
                    1.0, ...
                    'HitTest', ...
                    'off', ...
                    'Tag', ...
                    'CosmicWNFocusOverlay');
                
                xline( ...
                    ax, ...
                    x2, ...
                    ':', ...
                    'Color', ...
                    [0.35 0.35 0.35], ...
                    'LineWidth', ...
                    1.0, ...
                    'HitTest', ...
                    'off', ...
                    'Tag', ...
                    'CosmicWNFocusOverlay');
            end
            
            % =============================================================
            % AUXILIARY AUTOMATIC REGION
            % =============================================================
            
            auxLow = ...
                min(app.AuxCosmicRange);
            
            auxHigh = ...
                max(app.AuxCosmicRange);
            
            if max(wn) >= auxLow && ...
                    min(wn) <= auxHigh
                
                xline( ...
                    ax, ...
                    auxLow, ...
                    '--', ...
                    'Aux start', ...
                    'Color', ...
                    [0.75 0.20 0.10], ...
                    'LineWidth', ...
                    1.1, ...
                    'LabelVerticalAlignment', ...
                    'bottom', ...
                    'HitTest', ...
                    'off', ...
                    'Tag', ...
                    'CosmicWNFocusOverlay');
                
                xline( ...
                    ax, ...
                    auxHigh, ...
                    '--', ...
                    'Aux end', ...
                    'Color', ...
                    [0.75 0.20 0.10], ...
                    'LineWidth', ...
                    1.1, ...
                    'LabelVerticalAlignment', ...
                    'bottom', ...
                    'HitTest', ...
                    'off', ...
                    'Tag', ...
                    'CosmicWNFocusOverlay');
            end
            
            ylim(ax, yl);
            
            if ~originalHold
                hold(ax, 'off');
            end
        end
        
        function cube = getActiveCube(app)
            
            % =============================================================
            % DISPLAY CUBE
            %
            % Priority:
            %   1. Temporary trial preview.
            %   2. Committed corrected cube.
            %   3. Original raw cube.
            %
            % Do not use this function for saving or downstream processing
            % because it may return TrialCorrectedCube.
            % =============================================================
            
            if app.CorrectionTrialMode && ...
                    ~isempty(app.TrialCorrectedCube)
                
                cube = ...
                    app.TrialCorrectedCube;
                
            elseif ~isempty(app.CorrectedCube)
                
                cube = ...
                    app.CorrectedCube;
                
            else
                
                cube = ...
                    app.CubeOriginal;
            end
        end
        
        
        function cube = getCommittedCube(app)
            
            % =============================================================
            % COMMITTED PROCESSING CUBE
            %
            % This function never returns TrialCorrectedCube.
            % Use it for:
            %   - Artifact detection
            %   - Artifact saving
            %   - Background input
            %   - Returning data to other apps
            % =============================================================
            
            if ~isempty(app.CorrectedCube)
                
                cube = ...
                    app.CorrectedCube;
                
            else
                
                cube = ...
                    app.CubeOriginal;
            end
        end
        
        function params = sanitizeDetectionParams(app, params)
            
            % =============================================================
            % DEFAULT COMPLETE PARAMETER SET
            % =============================================================
            
            defaults = struct( ...
                'Mode', "Medium", ...
                'Method', "Derivative + local residual", ...
                'DerivativeOrder', 2, ...
                'ThresholdK', 8, ...
                'MinJumpK', 6, ...
                'LocalWindow', 9, ...
                'MaxSpikeWidth', 3, ...
                'MergeDistanceR', 3, ...
                'MaxAutomaticWidthR', 3, ...
                'SupportReturnK', 2.5, ...
                'WeakDerivativeFraction', 0.40, ...
                'WeakResidualFraction', 0.35);
            
            if isempty(params) || ...
                    ~isstruct(params)
                
                params = defaults;
            end
            
            defaultNames = ...
                fieldnames(defaults);
            
            for k = 1:numel(defaultNames)
                
                fieldName = ...
                    defaultNames{k};
                
                if ~isfield(params, fieldName) || ...
                        isempty(params.(fieldName))
                    
                    params.(fieldName) = ...
                        defaults.(fieldName);
                end
            end
            
            % =============================================================
            % TEXT FIELDS
            % =============================================================
            
            params.Mode = ...
                string(params.Mode);
            
            if strlength(params.Mode) == 0
                params.Mode = "Medium";
            end
            
            params.Method = ...
                string(params.Method);
            
            if strlength(params.Method) == 0
                params.Method = ...
                    "Derivative + local residual";
            end
            
            % =============================================================
            % NUMERIC FIELDS
            % =============================================================
            
            params.DerivativeOrder = ...
                double(params.DerivativeOrder);
            
            params.ThresholdK = ...
                double(params.ThresholdK);
            
            params.MinJumpK = ...
                double(params.MinJumpK);
            
            params.LocalWindow = ...
                double(params.LocalWindow);
            
            params.MaxSpikeWidth = ...
                double(params.MaxSpikeWidth);
            
            params.MergeDistanceR = ...
                double(params.MergeDistanceR);
            
            params.MaxAutomaticWidthR = ...
                double(params.MaxAutomaticWidthR);
            
            params.SupportReturnK = ...
                double(params.SupportReturnK);
            
            params.WeakDerivativeFraction = ...
                double(params.WeakDerivativeFraction);
            
            params.WeakResidualFraction = ...
                double(params.WeakResidualFraction);
            
            % Replace invalid values.
            numericDefaults = { ...
                'DerivativeOrder', 2; ...
                'ThresholdK', 8; ...
                'MinJumpK', 6; ...
                'LocalWindow', 9; ...
                'MaxSpikeWidth', 3; ...
                'MergeDistanceR', 3; ...
                'MaxAutomaticWidthR', 3; ...
                'SupportReturnK', 2.5; ...
                'WeakDerivativeFraction', 0.40; ...
                'WeakResidualFraction', 0.35};
            
            for k = 1:size(numericDefaults, 1)
                
                fieldName = ...
                    numericDefaults{k, 1};
                
                defaultValue = ...
                    numericDefaults{k, 2};
                
                value = ...
                    params.(fieldName);
                
                if ~isscalar(value) || ...
                        ~isfinite(value)
                    
                    params.(fieldName) = ...
                        defaultValue;
                end
            end
            
            % =============================================================
            % VALID RANGES
            % =============================================================
            
            params.DerivativeOrder = ...
                max(1, min(2, round(params.DerivativeOrder)));
            
            params.ThresholdK = ...
                max(1, params.ThresholdK);
            
            params.MinJumpK = ...
                max(1, params.MinJumpK);
            
            params.LocalWindow = ...
                max(5, round(params.LocalWindow));
            
            if mod(params.LocalWindow, 2) == 0
                params.LocalWindow = ...
                    params.LocalWindow + 1;
            end
            
            params.MaxSpikeWidth = ...
                max(1, round(params.MaxSpikeWidth));
            
            params.MergeDistanceR = ...
                max(0.5, params.MergeDistanceR);
            
            params.MaxAutomaticWidthR = ...
                max(1, params.MaxAutomaticWidthR);
            
            params.SupportReturnK = ...
                max(0.5, params.SupportReturnK);
            
            params.WeakDerivativeFraction = ...
                max(0.05, min(1.0, ...
                params.WeakDerivativeFraction));
            
            params.WeakResidualFraction = ...
                max(0.05, min(1.0, ...
                params.WeakResidualFraction));
        end


        function initializeCosmicControls(app)
            
            % =============================================================
            % TREE MULTI-SELECTION
            % =============================================================
            
            try
                app.Tree.Multiselect = ...
                    'on';
            catch
                % Older MATLAB releases may not expose this property.
            end
            % =============================================================
            % KEEP CURRENT VALID VALUES
            % =============================================================
            
            currentDetectionMode = '';
            currentCorrectionMethod = '';
            currentCorrectionMode = '';
            
            try
                currentDetectionMode = ...
                    char(string(app.ModesDropDown.Value));
            catch
            end
            
            try
                currentCorrectionMethod = ...
                    char(string(app.MethodsDropDown_2.Value));
            catch
            end
            
            try
                currentCorrectionMode = ...
                    char(string(app.ModesDropDown_2.Value));
            catch
            end
            
            % =============================================================
            % COSMIC DETECTION DROPDOWN
            %
            % Do not use ItemsData.
            % =============================================================
            
            detectionItems = { ...
                'Heavy', ...
                'Medium', ...
                'Light', ...
                'Manual'};
            
            app.ModesDropDownLabel.Text = ...
                'Modes';
            
            app.ModesDropDown.Items = ...
                detectionItems;
            
            if any(strcmp(currentDetectionMode, detectionItems))
                
                app.ModesDropDown.Value = ...
                    currentDetectionMode;
                
            else
                
                app.ModesDropDown.Value = ...
                    'Medium';
            end
            
            app.ModesDropDown.Enable = ...
                'on';
            
            app.ModesDropDown.Tooltip = sprintf([ ...
                'Cosmic detection sensitivity:\n' ...
                'Heavy: sensitive; lower thresholds and more candidates.\n' ...
                'Medium: balanced and recommended for the first run.\n' ...
                'Light: conservative; fewer candidates.\n' ...
                'Manual: edit all detection parameters.']);
            
            app.CosmicDetectionMode = ...
                char(app.ModesDropDown.Value);
            
            % =============================================================
            % INITIAL DETECTION PARAMETERS
            %
            % Keep current valid structure when possible.
            % =============================================================
            
            if isempty(app.DetectionParams) || ...
                    ~isstruct(app.DetectionParams)
                
                app.DetectionParams = struct( ...
                    'Mode', "Medium", ...
                    'Method', "Derivative + local residual", ...
                    'DerivativeOrder', 2, ...
                    'ThresholdK', 8, ...
                    'MinJumpK', 6, ...
                    'LocalWindow', 9, ...
                    'MaxSpikeWidth', 3, ...
                    'MergeDistanceR', 3, ...
                    'MaxAutomaticWidthR', 3, ...
                    'SupportReturnK', 2.5, ...
                    'WeakDerivativeFraction', 0.40, ...
                    'WeakResidualFraction', 0.35);
            end
            
            app.DetectionParams = ...
                app.sanitizeDetectionParams( ...
                app.DetectionParams);
            
            app.DetectionParams.Mode = ...
                string(app.CosmicDetectionMode);
            
            % =============================================================
            % COSMIC CORRECTION METHOD DROPDOWN
            %
            % Do not use ItemsData.
            % =============================================================
            
            correctionMethodItems = { ...
                'Linear interpolation', ...
                'PCHIP interpolation', ...
                'Spline interpolation', ...
                'Local median'};
            
            app.MethodsDropDown_2.Items = ...
                correctionMethodItems;
            
            if any(strcmp( ...
                    currentCorrectionMethod, ...
                    correctionMethodItems))
                
                app.MethodsDropDown_2.Value = ...
                    currentCorrectionMethod;
                
            else
                
                app.MethodsDropDown_2.Value = ...
                    'PCHIP interpolation';
            end
            
            % =============================================================
            % COSMIC CORRECTION LEVEL DROPDOWN
            %
            % Do not use ItemsData.
            % =============================================================
            
            correctionModeItems = { ...
                'Light', ...
                'Medium', ...
                'Heavy'};
            
            app.ModesDropDown_2.Items = ...
                correctionModeItems;
            
            if any(strcmp( ...
                    currentCorrectionMode, ...
                    correctionModeItems))
                
                app.ModesDropDown_2.Value = ...
                    currentCorrectionMode;
                
            else
                
                app.ModesDropDown_2.Value = ...
                    'Medium';
            end
            
            app.CosmicCorrectionParameterMode = ...
                char(app.ModesDropDown_2.Value);
            
            % =============================================================
            % REVIEW BUTTONS
            % =============================================================
            
            % app.Button_3.Text = ...
            %     '✓';
            
            app.Button_3.Tooltip = ...
                'Agree selected candidate(s) or selected page.';
            
            % app.Button_2.Text = ...
            %     '✕';
            
            app.Button_2.Tooltip = ...
                'Ignore selected candidate(s) or selected page.';
            
            % app.Button_4.Text = ...
            %     '↑';
            
            app.Button_4.Tooltip = ...
                'Move to previous candidate or page.';
            
            % app.Button.Text = ...
            %     '↓';
            
            app.Button.Tooltip = ...
                'Move to next candidate or page.';
            app.ManualdetectButton.Tooltip = ...
                ['Select a suspected missing cosmic region on the current ' ...
                'spectrum and append it to the review list.'];
            
            app.DeletespectraButton.Tooltip = ...
                ['Review unusable spectra and exclude selected pixels without ' ...
                'changing the Raman cube dimensions.'];
            
            % =============================================================
            % TEXT AREAS
            % =============================================================
            
            app.TextArea.Editable = ...
                'off';
            
            app.TextArea_5.Editable = ...
                'off';
        end
        
        function sig = detectionParamSignature(app, params)
            
            params = ...
                app.sanitizeDetectionParams(params);
            
            sig = sprintf( ...
                ['mode=%s|method=%s|d=%d|tk=%.5g|jk=%.5g|' ...
                'lw=%d|mw=%d|mergeR=%.5g|maxR=%.5g|' ...
                'returnK=%.5g|weakD=%.5g|weakR=%.5g|' ...
                'resolution=%.5g'], ...
                char(params.Mode), ...
                char(params.Method), ...
                params.DerivativeOrder, ...
                params.ThresholdK, ...
                params.MinJumpK, ...
                params.LocalWindow, ...
                params.MaxSpikeWidth, ...
                params.MergeDistanceR, ...
                params.MaxAutomaticWidthR, ...
                params.SupportReturnK, ...
                params.WeakDerivativeFraction, ...
                params.WeakResidualFraction, ...
                app.SpectralResolution);
        end
        
        function [mask, scoreVol, candidateTable] = ...
                detectCosmicDerivative(app, cube, params)
            
            % =============================================================
            % 1. VALIDATE
            % =============================================================
            
            params = ...
                app.sanitizeDetectionParams(params);
            
            C = ...
                single(cube);
            
            [ny, nx, nWn] = ...
                size(C);
            
            usable2D = ...
                app.getCurrentUsableSpectrumMask();
            
            if isempty(usable2D)
                usable2D = true(ny, nx);
            end
            
            % =============================================================
            % 2. DERIVATIVE EVIDENCE
            % =============================================================
            
            if params.DerivativeOrder == 1
                
                D = ...
                    abs(diff(C, 1, 3));
                
                D = ...
                    cat(3, D(:, :, 1), D);
                
            else
                
                D = ...
                    abs(diff(C, 2, 3));
                
                D = ...
                    cat( ...
                    3, ...
                    zeros(ny, nx, 1, 'single'), ...
                    D, ...
                    zeros(ny, nx, 1, 'single'));
            end
            
            % =============================================================
            % 3. LOCAL RESIDUAL
            % =============================================================
            
            localWindow = ...
                max(5, round(params.LocalWindow));
            
            if mod(localWindow, 2) == 0
                localWindow = localWindow + 1;
            end
            
            localMed = ...
                movmedian( ...
                C, ...
                localWindow, ...
                3, ...
                'omitnan');
            
            residual = ...
                C - localMed;
            
            positiveResidual = ...
                residual(residual > 0 & isfinite(residual));
            
            derivativeSigma = ...
                app.robustSigmaSingle(D(:));
            
            residualSigma = ...
                app.robustSigmaSingle(positiveResidual(:));
            
            zD = ...
                D ./ single(max(derivativeSigma, eps));
            
            zR = ...
                residual ./ single(max(residualSigma, eps));
            
            % =============================================================
            % 4. STRONG SEEDS AND WEAK SUPPORT
            %
            % Strong seed starts an event.
            % Weak support permits shoulders, tails and doublets to be covered.
            % =============================================================
            
            strongSeed = ...
                zD >= params.ThresholdK & ...
                zR >= params.MinJumpK & ...
                residual > 0;
            
            weakDerivativeThreshold = ...
                max( ...
                1.5, ...
                params.WeakDerivativeFraction * ...
                params.ThresholdK);
            
            weakResidualThreshold = ...
                max( ...
                1.25, ...
                params.WeakResidualFraction * ...
                params.MinJumpK);
            
            weakSupport = ...
                zD >= weakDerivativeThreshold & ...
                zR >= weakResidualThreshold & ...
                residual > 0;
            
            % =============================================================
            % 5. APPLY WAVENUMBER MASK
            % =============================================================
            
            if ~isempty(app.CosmicWNMask) && ...
                    numel(app.CosmicWNMask) == nWn
                
                wnMask = ...
                    logical(app.CosmicWNMask(:));
                
            else
                
                wnMask = ...
                    true(nWn, 1);
            end
            
            strongSeed(:, :, ~wnMask) = ...
                false;
            
            weakSupport(:, :, ~wnMask) = ...
                false;
            
            % =============================================================
            % 6. APPLY SPATIAL VALID MASK
            % =============================================================
            
            spatialMask3D = ...
                repmat(usable2D, 1, 1, nWn);
            
            strongSeed = ...
                strongSeed & spatialMask3D;
            
            weakSupport = ...
                weakSupport & spatialMask3D;
            
            scoreVol = ...
                max(zD, zR);
            
            % =============================================================
            % 7. BUILD EVENT TABLE
            % =============================================================
            
            candidateTable = ...
                app.buildResolutionAwareCandidateTable( ...
                strongSeed, ...
                weakSupport, ...
                scoreVol, ...
                residual, ...
                params);
            
            % Final display mask uses complete event support.
            mask = ...
                false(ny, nx, nWn);
            
            if ~isempty(candidateTable)
                
                for i = 1:height(candidateTable)
                    
                    mask( ...
                        candidateTable.Y(i), ...
                        candidateTable.X(i), ...
                        candidateTable.KStart(i): ...
                        candidateTable.KEnd(i)) = true;
                end
            end
        end
        
        function s = robustSigmaSingle(app,v)
            v = double(v(isfinite(v)));
            if isempty(v)
                s = 1;
                return
            end
            s = 1.4826*mad(v,1);
            if s <= eps || isnan(s)
                s = std(v,'omitnan');
            end
            if s <= eps || isnan(s)
                s = 1;
            end
        end
        
        function T = buildCandidateTable(app,mask,scoreVol,params)
            
            [ny,nx,~] = size(mask);
            
            pix = find(any(mask,3));
            
            if isempty(pix)
                T = table();
                return
            end
            
            maxRuns = nnz(mask);
            
            ys = zeros(maxRuns,1);
            xs = zeros(maxRuns,1);
            kStarts = zeros(maxRuns,1);
            kEnds = zeros(maxRuns,1);
            kPeaks = zeros(maxRuns,1);
            wns = zeros(maxRuns,1);
            scores = zeros(maxRuns,1);
            
            count = 0;
            
            for p = 1:numel(pix)
                
                [y,x] = ind2sub([ny nx],pix(p));
                
                line = squeeze(mask(y,x,:));
                idx = find(line);
                
                if isempty(idx)
                    continue
                end
                
                breaks = [1; find(diff(idx)>1)+1; numel(idx)+1];
                
                for b = 1:numel(breaks)-1
                    
                    runIdx = idx(breaks(b):breaks(b+1)-1);
                    
                    if isempty(runIdx)
                        continue
                    end
                    
                    if numel(runIdx) > params.MaxSpikeWidth
                        continue
                    end
                    
                    scLine = squeeze(scoreVol(y,x,runIdx));
                    [sc,loc] = max(double(scLine));
                    kPeak = runIdx(loc);
                    
                    count = count + 1;
                    
                    ys(count) = y;
                    xs(count) = x;
                    kStarts(count) = runIdx(1);
                    kEnds(count) = runIdx(end);
                    kPeaks(count) = kPeak;
                    
                    if ~isempty(app.Wavenumber) && numel(app.Wavenumber) >= kPeak
                        wns(count) = app.Wavenumber(kPeak);
                    else
                        wns(count) = kPeak;
                    end
                    
                    scores(count) = sc;
                end
            end
            
            if count == 0
                T = table();
                return
            end
            
            ys = ys(1:count);
            xs = xs(1:count);
            kStarts = kStarts(1:count);
            kEnds = kEnds(1:count);
            kPeaks = kPeaks(1:count);
            wns = wns(1:count);
            scores = scores(1:count);
            
            % Scientific audit rule:
            % Page 1 contains the highest-score candidates.
            [scores,ord] = sort(scores,'descend');
            
            ys = ys(ord);
            xs = xs(ord);
            kStarts = kStarts(ord);
            kEnds = kEnds(ord);
            kPeaks = kPeaks(ord);
            wns = wns(ord);
            
            ids = (1:count)';
            accepted = false(count,1);
            reviewed = false(count,1);
            corrected = false(count,1);
            
            T = table( ...
                ids, ...
                ys, ...
                xs, ...
                kStarts, ...
                kEnds, ...
                kPeaks, ...
                wns, ...
                scores, ...
                accepted, ...
                reviewed, ...
                corrected, ...
                'VariableNames', ...
                {'ID','Y','X','KStart','KEnd','KPeak','WN','Score','Accepted','Reviewed','Corrected'});
        end
        
        function prepareBackgroundStats(app,cube)
            if isempty(cube)
                app.BackgroundMeanSpectrum = [];
                app.BackgroundStdSpectrum = [];
                return
            end
            [ny,nx,nWn] = size(cube);
            if ~isempty(app.CurrentROIMask) && any(app.CurrentROIMask(:))
                pix = find(app.CurrentROIMask);
            else
                pix = (1:ny*nx)';
            end
            if numel(pix) > app.MaxStatsSpectra
                rng(1);
                pix = pix(randperm(numel(pix),app.MaxStatsSpectra));
            end
            X = reshape(cube,ny*nx,nWn);
            X = X(pix,:);
            app.BackgroundMeanSpectrum = mean(X,1,'omitnan');
            app.BackgroundStdSpectrum = std(X,0,1,'omitnan');
            app.BackgroundSampleLinearIdx = pix;
        end
        
        function displayCandidate(app,idx)
            if isempty(app.CandidateTable) || idx < 1 || idx > height(app.CandidateTable)
                return
            end
            app.CurrentCandidateIndex = idx;
            app.plotCandidateDetectionView(idx);
            app.updateCandidateText(idx);
        end
        
        function plotCandidateDetectionView(app,idx)
            T = app.CandidateTable;
            cube = app.getActiveCube();
            spec = squeeze(cube(T.Y(idx),T.X(idx),:));
            kPeak = T.KPeak(idx);
            ax1 = app.UIAxes;
            ax2 = app.UIAxes2;
            localIdx = app.getWNWindow(kPeak,90,35);
            fullIdx = (1:numel(app.Wavenumber))';
            wLocal = app.Wavenumber(localIdx);
            wFull = app.Wavenumber(fullIdx);
            
            app.resetSpectrumAxis(ax1);
            plot(ax1,wLocal,spec(localIdx),'b-','LineWidth',1.5);
            hold(ax1,'on');
            plot(ax1,app.Wavenumber(kPeak),spec(kPeak),'ro','MarkerFaceColor','r','MarkerSize',5);
            xline(ax1,app.Wavenumber(kPeak),'r--','LineWidth',1.1);
            hold(ax1,'off');
            title(ax1,sprintf('Candidate %03d | Y=%d X=%d | %.1f cm^{-1}',idx,T.Y(idx),T.X(idx),T.WN(idx)),'Interpreter','none');
            app.setNiceSpectralLimits(ax1,wLocal,spec(localIdx));
            
            if isempty(app.BackgroundMeanSpectrum)
                app.prepareBackgroundStats(cube);
            end
            m = app.BackgroundMeanSpectrum(:);
            s = app.BackgroundStdSpectrum(:);
            upper = m(fullIdx)+s(fullIdx);
            lower = m(fullIdx)-s(fullIdx);
            
            app.resetSpectrumAxis(ax2);
            fill(ax2,[wFull; flipud(wFull)],[upper; flipud(lower)],[0.5 0.5 0.5],...
                'FaceAlpha',0.22,'EdgeColor','none');
            hold(ax2,'on');
            plot(ax2,wFull,m(fullIdx),'Color',[0.45 0 0.75],'LineWidth',1.5);
            plot(ax2,wFull,spec(fullIdx),'b-','LineWidth',1.2);
            plot(ax2,app.Wavenumber(kPeak),spec(kPeak),'ro','MarkerFaceColor','r','MarkerSize',5);
            xline(ax2,app.Wavenumber(kPeak),'r--','LineWidth',1.1);
            hold(ax2,'off');
            title(ax2,'Full spectrum context: current spectrum, mean spectrum and ±SD');
            legend(ax2,{'Mean ± SD','Mean spectrum','Current spectrum','Detected peak'},'Location','best');
            
            yAll = [upper(:); lower(:); spec(fullIdx)];
            app.setNiceSpectralLimits(ax2,wFull,yAll);
            app.fixAxisMargin(ax1,true);
            app.fixAxisMargin(ax2,true);
        end
        
        function updateCandidateText(app,idx)
            
            T = app.CandidateTable;
            status = app.getCandidateStatus(idx);
            
            if app.CosmicConfirmed
                reviewState = 'Confirmed list: unaccepted candidates are ignored.';
            else
                reviewState = 'Detection list: review candidates by score-ranked pages.';
            end
            
            lines = {...
                'COSMIC CANDIDATE EVALUATION';...
                '================================';...
                '';...
                ['Scan: ' app.CurrentScanName];...
                sprintf('Candidate ID: %03d',idx);...
                sprintf('Status: %s',status);...
                sprintf('Pixel position: Y=%d, X=%d',T.Y(idx),T.X(idx));...
                sprintf('Peak position: %.2f cm^{-1}',T.WN(idx));...
                sprintf('Spectral index range: KStart=%d, KPeak=%d, KEnd=%d', ...
                T.KStart(idx),T.KPeak(idx),T.KEnd(idx));...
                sprintf('Spike width: %d spectral point(s)',T.KEnd(idx)-T.KStart(idx)+1);...
                sprintf('Detection score: %.3f',T.Score(idx));...
                '';...
                'Paging rule:';...
                '  Candidates are sorted by descending detection score.';...
                '  Page 1 contains the highest-score candidates.';...
                '  Score indicates high-frequency outlier strength, not automatic truth.';...
                '';...
                ['Review state: ' reviewState];...
                '';...
                'Interpretation guide:';...
                '- Very narrow isolated positive peak = likely cosmic spike.';...
                '- Peak inconsistent with neighboring average spectrum = stronger cosmic evidence.';...
                '- Broad Raman band or repeated biological feature = do NOT accept.';...
                '';...
                'Current detection parameters:';...
                sprintf('Method: %s',char(app.DetectionParams.Method));...
                sprintf('DerivativeOrder: %d',app.DetectionParams.DerivativeOrder);...
                sprintf('ThresholdK: %.2f',app.DetectionParams.ThresholdK);...
                sprintf('MinJumpK: %.2f',app.DetectionParams.MinJumpK);...
                sprintf('LocalWindow: %d',app.DetectionParams.LocalWindow);...
                sprintf('MaxSpikeWidth: %d',app.DetectionParams.MaxSpikeWidth)};
            
            app.setText(lines);
        end
        
        function idx = getWNWindow(app,kPeak,spanWN,minPoints)
            n = numel(app.Wavenumber);
            center = app.Wavenumber(kPeak);
            idx = find(abs(app.Wavenumber-center) <= spanWN);
            if numel(idx) < minPoints
                k1 = max(1,kPeak-round(minPoints/2));
                k2 = min(n,kPeak+round(minPoints/2));
                idx = (k1:k2)';
            end
            idx = idx(:);
        end
        
        function plotConfirmedReview(app)
            acceptedIdx = find(app.CandidateTable.Accepted(:))';
            if isempty(acceptedIdx)
                return
            end
            cube = app.getActiveCube();
            [ny,nx,nWn] = size(cube);
            ax = app.UIAxes;
            app.resetSpectrumAxis(ax);
            hold(ax,'on');
            if numel(acceptedIdx) > app.MaxReviewPlotSpectra
                rng(1);
                acceptedIdx = acceptedIdx(randperm(numel(acceptedIdx),app.MaxReviewPlotSpectra));
            end
            yAll = [];
            for i = acceptedIdx
                y = app.CandidateTable.Y(i);
                x = app.CandidateTable.X(i);
                spec = squeeze(cube(y,x,:));
                plot(ax,app.Wavenumber,spec,'Color',[0.78 0.78 0.78],'LineWidth',0.35);
                yAll = [yAll; spec(:)];
            end
            for i = acceptedIdx
                y = app.CandidateTable.Y(i);
                x = app.CandidateTable.X(i);
                k = app.CandidateTable.KPeak(i);
                spec = squeeze(cube(y,x,:));
                plot(ax,app.Wavenumber(k),spec(k),'r.','MarkerSize',10);
            end
            hold(ax,'off');
            title(ax,sprintf('Review accepted candidates | spectra=%d | scan=%s',numel(acceptedIdx),app.CurrentScanName),'Interpreter','none');
            app.setNiceSpectralLimits(ax,app.Wavenumber,yAll);
            ax2 = app.UIAxes2;
            app.resetImageAxis(ax2);
            if isempty(app.ConfirmedMask)
                app.ConfirmedMask = app.buildMaskFromAcceptedCandidates();
            end
            countMap = sum(app.ConfirmedMask,3);
            imagesc(ax2,countMap);
            axis(ax2,'image');
            ax2.YDir = 'reverse';
            colormap(ax2,'hot');
            colorbar(ax2);
            title(ax2,'Confirmed candidate count map');
            grid(ax2,'off');
            box(ax2,'on');
        end


        function idx = getCurrentAcceptedCandidateIndex(app)
            idx = [];
            selected = app.getSelectedCandidateIndices();
            if ~isempty(selected)
                selected = selected(app.CandidateTable.Accepted(selected));
                if ~isempty(selected)
                    idx = selected(1);
                    return
                end
            end
            if ~isnan(app.CurrentCandidateIndex) && app.CurrentCandidateIndex >= 1 && app.CurrentCandidateIndex <= height(app.CandidateTable) && app.CandidateTable.Accepted(app.CurrentCandidateIndex)
                idx = app.CurrentCandidateIndex;
                return
            end
            accepted = find(app.CandidateTable.Accepted,1,'first');
            if ~isempty(accepted)
                idx = accepted;
            end
        end
        
        function plotTrialCorrection(app,idx)
            T = app.CandidateTable;
            baseCube = app.getActiveCube();
            y = T.Y(idx);
            x = T.X(idx);
            beforeSpec = squeeze(baseCube(y,x,:));
            afterSpec = app.correctSingleSpectrum(beforeSpec,T.KStart(idx),T.KEnd(idx),app.CorrectParams);
            ax1 = app.UIAxes;
            ax2 = app.UIAxes2;
            app.resetSpectrumAxis(ax1);
            hold(ax1,'on');
            bgPix = app.getBackgroundSamplePixels(app.MaxBackgroundSpectra);
            [ny,nx,nWn] = size(baseCube);
            X = reshape(baseCube,ny*nx,nWn);
            yAll = beforeSpec(:);
            for i = 1:numel(bgPix)
                bg = X(bgPix(i),:);
                plot(ax1,app.Wavenumber,bg,'Color',[0.78 0.78 0.78],'LineWidth',0.35);
                yAll = [yAll; bg(:)];
            end
            plot(ax1,app.Wavenumber,beforeSpec,'b-','LineWidth',1.5);
            hold(ax1,'off');
            title(ax1,sprintf('Trial correction background context | Candidate %03d',idx),'Interpreter','none');
            app.setNiceSpectralLimits(ax1,app.Wavenumber,yAll);
            localIdx = app.getWNWindow(T.KPeak(idx),110,45);
            app.resetSpectrumAxis(ax2);
            plot(ax2,app.Wavenumber(localIdx),beforeSpec(localIdx),'r--','LineWidth',1.3);
            hold(ax2,'on');
            plot(ax2,app.Wavenumber(localIdx),afterSpec(localIdx),'b-','LineWidth',1.5);
            plot(ax2,app.Wavenumber(T.KPeak(idx)),beforeSpec(T.KPeak(idx)),'ro','MarkerFaceColor','r','MarkerSize',5);
            xline(ax2,app.Wavenumber(T.KPeak(idx)),'r--','LineWidth',1.0);
            hold(ax2,'off');
            title(ax2,sprintf('Before vs trial corrected | %s',char(app.CorrectParams.Method)),'Interpreter','none');
            legend(ax2,{'Before correction','After trial correction','Detected peak'},'Location','best');
            app.setNiceSpectralLimits(ax2,app.Wavenumber(localIdx),[beforeSpec(localIdx); afterSpec(localIdx)]);
            app.fixAxisMargin(ax1,true);
            app.fixAxisMargin(ax2,true);
            app.setText({...
                'TRIAL CORRECTION';...
                '';...
                sprintf('Candidate ID: %03d',idx);...
                sprintf('Pixel: Y=%d, X=%d',y,x);...
                sprintf('Peak: %.2f cm^{-1}',T.WN(idx));...
                ['Method: ' char(app.CorrectParams.Method)];...
                sprintf('ExpandPoints: %d',app.CorrectParams.ExpandPoints);...
                sprintf('SupportPoints: %d',app.CorrectParams.SupportPoints);...
                sprintf('MedianWindow: %d',app.CorrectParams.MedianWindow);...
                '';...
                'Axis 1: 100 background spectra in light gray and current spectrum in blue.';...
                'Axis 2: before correction in red dashed line, trial correction in blue.'});
        end
        
        function pix = getBackgroundSamplePixels(app,maxN)
            cube = app.getActiveCube();
            [ny,nx,~] = size(cube);
            if ~isempty(app.CurrentROIMask) && any(app.CurrentROIMask(:))
                pix = find(app.CurrentROIMask);
            else
                pix = (1:ny*nx)';
            end
            if numel(pix) > maxN
                rng(1);
                pix = pix(randperm(numel(pix),maxN));
            end
        end
        
        function specCorrected = correctSingleSpectrum(app,spec,kStart,kEnd,params)
            spec = double(spec(:));
            n = numel(spec);
            t1 = max(1,kStart-params.ExpandPoints);
            t2 = min(n,kEnd+params.ExpandPoints);
            target = (t1:t2)';
            left = max(1,t1-params.SupportPoints):max(1,t1-1);
            right = min(n,t2+1):min(n,t2+params.SupportPoints);
            support = [left(:); right(:)];
            support = support(~ismember(support,target));
            specCorrected = spec;
            if strcmp(params.Method,'Local median')
                halfWin = floor(params.MedianWindow/2);
                for k = target'
                    win = max(1,k-halfWin):min(n,k+halfWin);
                    win = setdiff(win,target);
                    if isempty(win)
                        win = support;
                    end
                    if isempty(win)
                        continue
                    end
                    specCorrected(k) = median(spec(win),'omitnan');
                end
                return
            end
            if numel(support) < 2
                fillVal = median(spec(max(1,t1-3):min(n,t2+3)),'omitnan');
                specCorrected(target) = fillVal;
                return
            end
            try
                if strcmp(params.Method,'Linear interpolation')
                    specCorrected(target) = interp1(support,spec(support),target,'linear','extrap');
                elseif strcmp(params.Method,'PCHIP interpolation')
                    if numel(support) < 4
                        specCorrected(target) = interp1(support,spec(support),target,'linear','extrap');
                    else
                        specCorrected(target) = interp1(support,spec(support),target,'pchip','extrap');
                    end
                elseif strcmp(params.Method,'Spline interpolation')
                    if numel(support) < 4
                        specCorrected(target) = interp1(support,spec(support),target,'linear','extrap');
                    else
                        specCorrected(target) = interp1(support,spec(support),target,'spline','extrap');
                    end
                else
                    specCorrected(target) = interp1(support,spec(support),target,'linear','extrap');
                end
            catch
                specCorrected(target) = median(spec(support),'omitnan');
            end
        end
        
        function mask = buildMaskFromAcceptedCandidates(app)
            if isempty(app.CandidateTable)
                mask = logical.empty;
                return
            end
            [ny,nx,nWn] = size(app.CubeOriginal);
            mask = false(ny,nx,nWn);
            idxs = find(app.CandidateTable.Accepted(:))';
            for i = idxs
                y = app.CandidateTable.Y(i);
                x = app.CandidateTable.X(i);
                ks = app.CandidateTable.KStart(i):app.CandidateTable.KEnd(i);
                mask(y,x,ks) = true;
            end
        end
        
        function savedMatPath = saveFinalCosmicData(app, finalCube)
            
            app.initializeProjectPaths();
            
            if isempty(finalCube) || ...
                    ndims(finalCube) ~= 3
                
                error( ...
                    'Cannot save Cosmic data because finalCube is invalid.');
            end
            
            if ~isequal( ...
                    size(finalCube), ...
                    size(app.CubeOriginal))
                
                error( ...
                    'Final Cosmic cube size does not match CubeOriginal.');
            end
            
            scanID = ...
                app.getCurrentScanID();
            
            cosmicFolder = ...
                app.getCurrentCosmicDataFolder();
            
            savedMatPath = ...
                fullfile( ...
                cosmicFolder, ...
                [scanID '_cosmic_pretreated.mat']);
            
            originalSourcePath = ...
                app.getOriginalSourceMatPath();
            
            % =============================================================
            % ONE MATERIALIZED COSMIC CUBE
            %
            % Do not clone app.RamanData because the payload can contain
            % additional large arrays.
            % =============================================================
            
            RamanDataCosmic = struct();
            
            RamanDataCosmic.SchemaVersion = ...
                2;
            
            RamanDataCosmic.FormatVersion = ...
                'RamanCube Cosmic Checkpoint v2';
            
            RamanDataCosmic.StorageMode = ...
                'materialized_cube';
            
            RamanDataCosmic.Stage = ...
                'Cosmic';
            
            RamanDataCosmic.ScanName = ...
                app.CurrentScanName;
            
            RamanDataCosmic.ScanID = ...
                scanID;
            
            RamanDataCosmic.Cube = ...
                double(finalCube);
            
            RamanDataCosmic.Wavenumber = ...
                double(app.Wavenumber(:));
            
            RamanDataCosmic.OriginalSourceMatPath = ...
                originalSourcePath;
            
            RamanDataCosmic.OriginalSourceRelativePath = ...
                app.makeProjectRelativePath( ...
                originalSourcePath);
            
            % =============================================================
            % VALID PIXELS
            % =============================================================
            
            ValidPixelMask2D = ...
                all(isfinite(finalCube), 3);
            
            if isstruct(app.RamanData) && ...
                    isfield(app.RamanData, 'ValidMask') && ...
                    isequal( ...
                    size(app.RamanData.ValidMask), ...
                    size(ValidPixelMask2D))
                
                ValidPixelMask2D = ...
                    ValidPixelMask2D & ...
                    logical(app.RamanData.ValidMask);
            end
            
            if ~isempty(app.DeletedSpectrumMask2D) && ...
                    isequal( ...
                    size(app.DeletedSpectrumMask2D), ...
                    size(ValidPixelMask2D))
                
                ValidPixelMask2D = ...
                    ValidPixelMask2D & ...
                    ~logical(app.DeletedSpectrumMask2D);
            end
            
            RamanDataCosmic.ValidMask = ...
                ValidPixelMask2D;
            
            % =============================================================
            % COSMIC STATE
            % =============================================================
            
            if isempty(app.ConfirmedMask) && ...
                    ~isempty(app.CandidateTable)
                
                app.ConfirmedMask = ...
                    app.buildMaskFromAcceptedCandidates();
            end
            
            CosmicMask = ...
                app.ConfirmedMask;
            
            CandidateTable = ...
                app.CandidateTable;
            
            CandidateSummary = ...
                app.CandidateSummary;
            
            DetectionParams = ...
                app.DetectionParams;
            
            CorrectParams = ...
                app.CorrectParams;
            
            ROIMask = ...
                app.CurrentROIMask;
            
            CosmicWNMask = ...
                app.CosmicWNMask;
            
            CosmicWNMode = ...
                app.CosmicWNMode;
            
            CosmicWNIncludeRanges = ...
                app.CosmicWNIncludeRanges;
            
            CosmicWNExcludeRanges = ...
                app.CosmicWNExcludeRanges;
            
            AuxCosmicEnabled = ...
                app.AuxCosmicEnabled;
            
            AuxCosmicRange = ...
                app.AuxCosmicRange;
            
            AuxCosmicMask = ...
                app.AuxCosmicMask;
            
            AuxCosmicCandidateTable = ...
                app.AuxCosmicCandidateTable;
            
            AuxCosmicCorrectedCount = ...
                app.AuxCosmicCorrectedCount;
            
            DeletedSpectrumMask2D = ...
                app.DeletedSpectrumMask2D;
            
            DeletedSpectrumTable = ...
                app.DeletedSpectrumTable;
            
            % =============================================================
            % PROCESSING INFO
            % =============================================================
            
            SessionMetadata = ...
                app.buildSessionMetadata();
            
            ProcessingInfo = struct();
            
            ProcessingInfo.SchemaVersion = ...
                2;
            
            ProcessingInfo.ScanName = ...
                app.CurrentScanName;
            
            ProcessingInfo.ScanID = ...
                scanID;
            
            ProcessingInfo.OriginalMatPath = ...
                originalSourcePath;
            
            ProcessingInfo.OriginalMatRelativePath = ...
                app.makeProjectRelativePath( ...
                originalSourcePath);
            
            ProcessingInfo.ProcessedDate = ...
                datestr(now, 'yyyy-mm-dd HH:MM:SS');
            
            ProcessingInfo.SoftwareStep = ...
                'scanpretreat cosmic removal';
            
            ProcessingInfo.OutputFolder = ...
                cosmicFolder;
            
            ProcessingInfo.OutputMatPath = ...
                savedMatPath;
            
            ProcessingInfo.StorageMode = ...
                'one materialized cosmic cube';
            
            ProcessingInfo.User = ...
                SessionMetadata.User;
            
            ProcessingInfo.Email = ...
                SessionMetadata.Email;
            
            ProcessingInfo.Role = ...
                SessionMetadata.Role;
            
            ProcessingInfo.SessionID = ...
                SessionMetadata.SessionID;
            
            % =============================================================
            % LIGHTWEIGHT PRETREAT STATE INSIDE SAME FILE
            % =============================================================
            
            PretreatState = ...
                app.buildCosmicPretreatState( ...
                savedMatPath, ...
                '');
            
            PretreatState.OriginalMatPath = ...
                originalSourcePath;
            
            PretreatState.StorageMode = ...
                'single_checkpoint_file';
            
            % =============================================================
            % ATOMIC SAVE
            % =============================================================
            
            tempPath = ...
                [savedMatPath '.tmp.mat'];
            
            save( ...
                tempPath, ...
                'RamanDataCosmic', ...
                'ValidPixelMask2D', ...
                'CosmicMask', ...
                'CandidateTable', ...
                'CandidateSummary', ...
                'DetectionParams', ...
                'CorrectParams', ...
                'ROIMask', ...
                'CosmicWNMask', ...
                'CosmicWNMode', ...
                'CosmicWNIncludeRanges', ...
                'CosmicWNExcludeRanges', ...
                'AuxCosmicEnabled', ...
                'AuxCosmicRange', ...
                'AuxCosmicMask', ...
                'AuxCosmicCandidateTable', ...
                'AuxCosmicCorrectedCount', ...
                'DeletedSpectrumMask2D', ...
                'DeletedSpectrumTable', ...
                'ProcessingInfo', ...
                'PretreatState', ...
                '-v7.3');
            
            if isfile(savedMatPath)
                delete(savedMatPath);
            end
            
            [ok, messageText] = ...
                movefile( ...
                tempPath, ...
                savedMatPath, ...
                'f');
            
            if ~ok
                
                error( ...
                    'Could not commit Cosmic checkpoint:%s%s', ...
                    newline, ...
                    messageText);
            end
            
        end
        
        function evidenceFolder = saveCosmicTraceFigures(app,finalCube)
            
            % Backward-compatible wrapper.
            evidenceFolder = app.saveCosmicEvidencePackage(finalCube);
        end
        
        function evidenceFolder = saveCosmicEvidencePackage(app,finalCube)
            
            app.initializeProjectPaths();
            
            evidenceFolder = '';
            
            if isempty(app.CandidateTable) || ~any(app.CandidateTable.Accepted)
                return
            end
            
            scanID = app.getCurrentScanID();
            
            evidenceFolder = fullfile(app.Paths.CosmicEvidenceRoot,scanID);
            
            topFolder = fullfile(evidenceFolder,'top');
            randomFolder = fullfile(evidenceFolder,'random');
            borderlineFolder = fullfile(evidenceFolder,'borderline');
            overviewFolder = fullfile(evidenceFolder,'overview');
            
            folders = {evidenceFolder,topFolder,randomFolder,borderlineFolder,overviewFolder};
            
            for i = 1:numel(folders)
                if ~exist(folders{i},'dir')
                    mkdir(folders{i});
                end
            end
            
            T = app.CandidateTable;
            acceptedIdx = find(T.Accepted(:));
            
            if isempty(acceptedIdx)
                return
            end
            
            % Top-score examples.
            [~,ordDesc] = sort(T.Score(acceptedIdx),'descend');
            topIdx = acceptedIdx(ordDesc);
            topIdx = topIdx(1:min(numel(topIdx),app.EvidenceTopN));
            
            % Random accepted examples, fixed seed for reproducibility.
            rng(app.EvidenceRandomSeed);
            randomPool = setdiff(acceptedIdx,topIdx,'stable');
            
            if isempty(randomPool)
                randomIdx = [];
            else
                rp = randperm(numel(randomPool),min(numel(randomPool),app.EvidenceRandomN));
                randomIdx = randomPool(rp);
            end
            
            % Borderline accepted examples = lowest-score accepted candidates.
            [~,ordAsc] = sort(T.Score(acceptedIdx),'ascend');
            borderlineIdx = acceptedIdx(ordAsc);
            borderlineIdx = borderlineIdx(1:min(numel(borderlineIdx),app.EvidenceBorderlineN));
            
            % Overview figures and reports.
            app.saveCosmicOverviewFigures(finalCube,overviewFolder);
            app.saveCosmicQCSummaryText(evidenceFolder,topIdx,randomIdx,borderlineIdx);
            
            allEvidenceIdx = unique([topIdx(:); randomIdx(:); borderlineIdx(:)],'stable');
            evidenceTable = app.makeCosmicEvidenceTable(allEvidenceIdx,finalCube);
            writetable(evidenceTable,fullfile(evidenceFolder,[scanID '_cosmic_evidence_table.csv']));
            
            % Save individual evidence figures.
            for n = 1:numel(topIdx)
                app.saveSingleCosmicEvidenceFigure(topIdx(n),finalCube,topFolder,'top',n);
            end
            
            for n = 1:numel(randomIdx)
                app.saveSingleCosmicEvidenceFigure(randomIdx(n),finalCube,randomFolder,'random',n);
            end
            
            for n = 1:numel(borderlineIdx)
                app.saveSingleCosmicEvidenceFigure(borderlineIdx(n),finalCube,borderlineFolder,'borderline',n);
            end
            
            app.LastCosmicEvidenceFolder = evidenceFolder;
        end
        
        
        function saveCosmicOverviewFigures(app,finalCube,overviewFolder)
            
            scanID = app.getCurrentScanID();
            
            T = app.CandidateTable;
            
            % 1. Score histogram.
            fig = figure('Visible','off','Color','w','Position',[100 100 1100 700]);
            ax = axes(fig);
            histogram(ax,T.Score,60);
            hold(ax,'on');
            
            if any(T.Accepted)
                histogram(ax,T.Score(T.Accepted),40);
            end
            
            hold(ax,'off');
            xlabel(ax,'Detection score');
            ylabel(ax,'Candidate count');
            title(ax,{ ...
                'Cosmic candidate score distribution', ...
                ['Scan: ' app.CurrentScanName]}, ...
                'Interpreter','none');
            legend(ax,{'All candidates','Accepted candidates'},'Location','best');
            box(ax,'on');
            exportgraphics(fig,fullfile(overviewFolder,[scanID '_candidate_score_histogram.png']),'Resolution',300);
            close(fig);
            
            % 2. Candidate count map.
            if isempty(app.ConfirmedMask)
                app.ConfirmedMask = app.buildMaskFromAcceptedCandidates();
            end
            
            countMap = sum(app.ConfirmedMask,3);
            
            fig = figure('Visible','off','Color','w','Position',[100 100 1000 850]);
            ax = axes(fig);
            imagesc(ax,countMap);
            axis(ax,'image');
            ax.YDir = 'reverse';
            colormap(ax,'hot');
            colorbar(ax);
            title(ax,{ ...
                'Confirmed cosmic candidate count map', ...
                ['Accepted candidates: ' num2str(nnz(T.Accepted))]}, ...
                'Interpreter','none');
            box(ax,'on');
            exportgraphics(fig,fullfile(overviewFolder,[scanID '_confirmed_candidate_count_map.png']),'Resolution',300);
            close(fig);
            
            % 3. Raw vs corrected map.
            if ~isempty(app.CubeOriginal)
                [rawMap,rawLabel] = app.buildRamanMap(app.CubeOriginal);
            else
                [rawMap,rawLabel] = app.buildRamanMap(finalCube);
            end
            
            [correctedMap,correctedLabel] = app.buildRamanMap(finalCube);
            
            fig = figure('Visible','off','Color','w','Position',[100 100 1450 650]);
            
            ax1 = axes(fig,'Position',[0.06 0.12 0.40 0.78]);
            imagesc(ax1,rawMap);
            axis(ax1,'image');
            ax1.YDir = 'normal';
            colormap(ax1,'hot');
            colorbar(ax1);
            title(ax1,{ ...
                'Raw/current input map', ...
                rawLabel}, ...
                'Interpreter','none');
            box(ax1,'on');
            
            ax2 = axes(fig,'Position',[0.56 0.12 0.40 0.78]);
            imagesc(ax2,correctedMap);
            axis(ax2,'image');
            ax2.YDir = 'normal';
            colormap(ax2,'hot');
            colorbar(ax2);
            title(ax2,{ ...
                'After cosmic correction map', ...
                correctedLabel}, ...
                'Interpreter','none');
            box(ax2,'on');
            
            exportgraphics(fig,fullfile(overviewFolder,[scanID '_raw_vs_corrected_map.png']),'Resolution',300);
            close(fig);
        end
        
        
        function saveCosmicQCSummaryText(app,evidenceFolder,topIdx,randomIdx,borderlineIdx)
            
            scanID = app.getCurrentScanID();
            
            outFile = fullfile(evidenceFolder,[scanID '_cosmic_QC_summary.txt']);
            
            T = app.CandidateTable;
            
            fid = fopen(outFile,'w');
            
            if fid < 0
                return
            end
            
            fprintf(fid,'COSMIC REMOVAL QC SUMMARY\n');
            fprintf(fid,'========================================\n\n');
            
            fprintf(fid,'Scan name: %s\n',app.CurrentScanName);
            fprintf(fid,'Scan ID: %s\n',scanID);
            fprintf(fid,'Project root: %s\n',app.ProjectRoot);
            fprintf(fid,'Original MAT path: %s\n',app.CurrentMatPath);
            fprintf(fid,'Processed date: %s\n\n',datestr(now,'yyyy-mm-dd HH:MM:SS'));
            
            fprintf(fid,'DETECTION SETTINGS\n');
            fprintf(fid,'Method: %s\n',char(app.DetectionParams.Method));
            fprintf(fid,'DerivativeOrder: %d\n',app.DetectionParams.DerivativeOrder);
            fprintf(fid,'ThresholdK: %.4f\n',app.DetectionParams.ThresholdK);
            fprintf(fid,'MinJumpK: %.4f\n',app.DetectionParams.MinJumpK);
            fprintf(fid,'LocalWindow: %d\n',app.DetectionParams.LocalWindow);
            fprintf(fid,'MaxSpikeWidth: %d\n',app.DetectionParams.MaxSpikeWidth);
            fprintf(fid,'WN mode: %s\n\n',app.CosmicWNMode);
            
            fprintf(fid,'CANDIDATE SUMMARY\n');
            fprintf(fid,'Total candidates detected: %d\n',height(T));
            fprintf(fid,'Accepted candidates: %d\n',nnz(T.Accepted));
            fprintf(fid,'Ignored candidates: %d\n',nnz(T.Reviewed & ~T.Accepted));
            fprintf(fid,'Pending candidates: %d\n',nnz(~T.Reviewed));
            fprintf(fid,'Corrected candidates: %d\n\n',nnz(T.Corrected));
            
            fprintf(fid,'PAGE SORTING RULE\n');
            fprintf(fid,'Candidates are sorted by descending detection score.\n');
            fprintf(fid,'Page 1 contains the highest-score candidates.\n');
            fprintf(fid,'The score reflects derivative/local-residual outlier evidence, not automatic truth.\n');
            fprintf(fid,'Final correction is applied only to user-accepted candidates.\n\n');
            
            fprintf(fid,'QC EVIDENCE SAMPLING\n');
            fprintf(fid,'Top-score examples: %d\n',numel(topIdx));
            fprintf(fid,'Random accepted examples: %d\n',numel(randomIdx));
            fprintf(fid,'Borderline accepted examples: %d\n',numel(borderlineIdx));
            fprintf(fid,'Random seed: %d\n\n',app.EvidenceRandomSeed);
            
            fprintf(fid,'INTERPRETATION\n');
            fprintf(fid,'A cosmic spike is expected to appear as a narrow, isolated, high-frequency positive outlier.\n');
            fprintf(fid,'Broad Raman bands or repeated biological spectral features should not be accepted as cosmic spikes.\n');
            fprintf(fid,'The evidence figures show raw/current spectra, corrected spectra, local spectral context, and detection metadata.\n');
            
            fclose(fid);
        end
        
        
        function evidenceTable = makeCosmicEvidenceTable(app,idxs,finalCube)
            
            T = app.CandidateTable;
            n = numel(idxs);
            
            CandidateID = zeros(n,1);
            Y = zeros(n,1);
            X = zeros(n,1);
            WN = zeros(n,1);
            KStart = zeros(n,1);
            KPeak = zeros(n,1);
            KEnd = zeros(n,1);
            SpikeWidth = zeros(n,1);
            Score = zeros(n,1);
            PeakBefore = zeros(n,1);
            PeakAfter = zeros(n,1);
            CorrectionDelta = zeros(n,1);
            LocalMedianBefore = zeros(n,1);
            PeakExcessBefore = zeros(n,1);
            Accepted = false(n,1);
            Corrected = false(n,1);
            
            for a = 1:n
                
                i = idxs(a);
                
                y = T.Y(i);
                x = T.X(i);
                k = T.KPeak(i);
                
                beforeSpec = squeeze(app.CubeOriginal(y,x,:));
                afterSpec = squeeze(finalCube(y,x,:));
                
                localIdx = app.getWNWindow(k,80,31);
                targetIdx = T.KStart(i):T.KEnd(i);
                supportIdx = setdiff(localIdx,targetIdx);
                
                if isempty(supportIdx)
                    supportIdx = localIdx;
                end
                
                localMed = median(beforeSpec(supportIdx),'omitnan');
                
                CandidateID(a) = i;
                Y(a) = y;
                X(a) = x;
                WN(a) = T.WN(i);
                KStart(a) = T.KStart(i);
                KPeak(a) = T.KPeak(i);
                KEnd(a) = T.KEnd(i);
                SpikeWidth(a) = T.KEnd(i) - T.KStart(i) + 1;
                Score(a) = T.Score(i);
                
                PeakBefore(a) = beforeSpec(k);
                PeakAfter(a) = afterSpec(k);
                CorrectionDelta(a) = beforeSpec(k) - afterSpec(k);
                LocalMedianBefore(a) = localMed;
                PeakExcessBefore(a) = beforeSpec(k) - localMed;
                
                Accepted(a) = T.Accepted(i);
                Corrected(a) = T.Corrected(i);
            end
            
            evidenceTable = table( ...
                CandidateID, ...
                Y, ...
                X, ...
                WN, ...
                KStart, ...
                KPeak, ...
                KEnd, ...
                SpikeWidth, ...
                Score, ...
                PeakBefore, ...
                PeakAfter, ...
                CorrectionDelta, ...
                LocalMedianBefore, ...
                PeakExcessBefore, ...
                Accepted, ...
                Corrected);
        end
        
        
        function saveSingleCosmicEvidenceFigure(app,idx,finalCube,outFolder,groupName,rankNumber)
            
            T = app.CandidateTable;
            
            y = T.Y(idx);
            x = T.X(idx);
            kPeak = T.KPeak(idx);
            
            beforeSpec = squeeze(app.CubeOriginal(y,x,:));
            afterSpec = squeeze(finalCube(y,x,:));
            
            localIdx = app.getWNWindow(kPeak,120,55);
            
            fig = figure('Visible','off','Color','w','Position',[100 100 1400 980]);
            
            ax1 = axes(fig,'Position',[0.07 0.60 0.87 0.32]);
            plot(ax1,app.Wavenumber(localIdx),beforeSpec(localIdx),'r--','LineWidth',1.3);
            hold(ax1,'on');
            plot(ax1,app.Wavenumber(localIdx),afterSpec(localIdx),'b-','LineWidth',1.5);
            plot(ax1,app.Wavenumber(kPeak),beforeSpec(kPeak),'ro','MarkerFaceColor','r','MarkerSize',5);
            xline(ax1,app.Wavenumber(kPeak),'r--','LineWidth',1.0);
            hold(ax1,'off');
            xlabel(ax1,'Wavenumber (cm^{-1})');
            ylabel(ax1,'Intensity');
            title(ax1,sprintf('%s evidence | Candidate %03d | %.2f cm^{-1}', ...
                upper(groupName),idx,T.WN(idx)), ...
                'Interpreter','none');
            legend(ax1,{'Before correction','After correction','Detected spike'},'Location','best');
            grid(ax1,'off');
            box(ax1,'on');
            app.setNiceSpectralLimits(ax1,app.Wavenumber(localIdx),[beforeSpec(localIdx); afterSpec(localIdx)]);
            
            ax2 = axes(fig,'Position',[0.07 0.36 0.87 0.17]);
            plot(ax2,app.Wavenumber,beforeSpec,'Color',[0.55 0.55 0.55],'LineWidth',0.9);
            hold(ax2,'on');
            plot(ax2,app.Wavenumber(kPeak),beforeSpec(kPeak),'ro','MarkerFaceColor','r','MarkerSize',5);
            xline(ax2,app.Wavenumber(kPeak),'r--','LineWidth',1.0);
            hold(ax2,'off');
            xlabel(ax2,'Wavenumber (cm^{-1})');
            ylabel(ax2,'Intensity');
            title(ax2,'Full raw spectrum context');
            box(ax2,'on');
            app.setNiceSpectralLimits(ax2,app.Wavenumber,beforeSpec);
            
            evidenceText = app.makeCosmicEvidenceText(idx,beforeSpec,afterSpec);
            
            annotation(fig,'textbox',[0.06 0.04 0.88 0.27], ...
                'String',evidenceText, ...
                'Interpreter','none', ...
                'FontName','Consolas', ...
                'FontSize',8.5, ...
                'VerticalAlignment','top', ...
                'HorizontalAlignment','left', ...
                'EdgeColor',[0.75 0.75 0.75], ...
                'BackgroundColor',[1 1 1], ...
                'FitBoxToText','off');
            
            scanID = app.getCurrentScanID();
            outName = sprintf('%s_%s_evidence_%02d_candidate_%03d.png', ...
                scanID,groupName,rankNumber,idx);
            
            exportgraphics(fig,fullfile(outFolder,outName),'Resolution',300);
            close(fig);
        end
        
        
        function txt = makeCosmicEvidenceText(app,idx,beforeSpec,afterSpec)
            
            T = app.CandidateTable;
            
            y = T.Y(idx);
            x = T.X(idx);
            kPeak = T.KPeak(idx);
            
            localIdx = app.getWNWindow(kPeak,80,31);
            targetIdx = T.KStart(idx):T.KEnd(idx);
            supportIdx = setdiff(localIdx,targetIdx);
            
            if isempty(supportIdx)
                supportIdx = localIdx;
            end
            
            localMed = median(beforeSpec(supportIdx),'omitnan');
            
            peakBefore = beforeSpec(kPeak);
            peakAfter = afterSpec(kPeak);
            deltaVal = peakBefore - peakAfter;
            peakExcess = peakBefore - localMed;
            
            txt = sprintf([ ...
                'COSMIC SPIKE QC EVIDENCE\n', ...
                'Scan: %s\n', ...
                'Candidate ID: %03d\n', ...
                'Pixel: Y=%d, X=%d\n', ...
                'Peak WN: %.2f cm^-1\n', ...
                'Spectral indices: KStart=%d, KPeak=%d, KEnd=%d\n', ...
                'Spike width: %d spectral point(s)\n', ...
                'Detection score: %.4f\n', ...
                'Decision: Accepted=%d | Corrected=%d\n\n', ...
                'LOCAL EVIDENCE METRICS\n', ...
                'Peak before correction: %.6g\n', ...
                'Peak after correction: %.6g\n', ...
                'Correction delta: %.6g\n', ...
                'Local median before correction: %.6g\n', ...
                'Peak excess above local median: %.6g\n\n', ...
                'DETECTION PARAMETERS\n', ...
                'Method: %s | DerivativeOrder: %d\n', ...
                'ThresholdK: %.3f | MinJumpK: %.3f | LocalWindow: %d | MaxSpikeWidth: %d\n\n', ...
                'CORRECTION PARAMETERS\n', ...
                'Method: %s | ExpandPoints: %d | SupportPoints: %d | MedianWindow: %d\n\n', ...
                'INTERPRETATION RULE\n', ...
                'A candidate is considered plausible cosmic evidence when it is narrow, isolated,\n', ...
                'positive, and inconsistent with the local spectral baseline/context.\n'], ...
                app.CurrentScanName, ...
                idx, ...
                y, ...
                x, ...
                T.WN(idx), ...
                T.KStart(idx), ...
                T.KPeak(idx), ...
                T.KEnd(idx), ...
                T.KEnd(idx)-T.KStart(idx)+1, ...
                T.Score(idx), ...
                T.Accepted(idx), ...
                T.Corrected(idx), ...
                peakBefore, ...
                peakAfter, ...
                deltaVal, ...
                localMed, ...
                peakExcess, ...
                char(app.DetectionParams.Method), ...
                app.DetectionParams.DerivativeOrder, ...
                app.DetectionParams.ThresholdK, ...
                app.DetectionParams.MinJumpK, ...
                app.DetectionParams.LocalWindow, ...
                app.DetectionParams.MaxSpikeWidth, ...
                char(app.CorrectParams.Method), ...
                app.CorrectParams.ExpandPoints, ...
                app.CorrectParams.SupportPoints, ...
                app.CorrectParams.MedianWindow);
        end
        
        function ok = returnPayloadToMainApp(app,payload)
            
            ok = false;
            
            if isempty(app.mainApp)
                return
            end
            
            try
                if ~isvalid(app.mainApp)
                    return
                end
                
                if ~isfield(payload,'Stage') || isempty(payload.Stage)
                    payload.Stage = 'Pretreat';
                end
                
                if ~isfield(payload,'ScanName') || isempty(payload.ScanName)
                    payload.ScanName = app.CurrentScanName;
                end
                
                if ~isfield(payload,'SourceApp') || isempty(payload.SourceApp)
                    payload.SourceApp = 'scanpretreat';
                end
                
                if ismethod(app.mainApp,'receivePretreatResult')
                    app.mainApp.receivePretreatResult(payload);
                    ok = true;
                end
                
                if ismethod(app.mainApp,'addHistory')
                    app.mainApp.addHistory(sprintf( ...
                        'scanpretreat returned %s result: %s', ...
                        char(payload.Stage), ...
                        char(payload.ScanName)));
                end
                
            catch ME
                ok = false;
                
                try
                    uialert(app.UIFigure,ME.message,'Return payload to Home error');
                catch
                end
            end
        end


        function safeName = makeSafeFileName(app,nameIn)
            safeName = regexprep(char(nameIn),'[^\w\-]','_');
        end
        
        function applyRobustCLim(app,ax,img)
            vals = img(isfinite(img));
            if isempty(vals)
                return
            end
            lo = prctile(vals,2);
            hi = prctile(vals,98);
            if lo < hi
                caxis(ax,[lo hi]);
            end
        end
        
        function setText(app,lines)
            if ischar(lines)
                app.TextArea.Value = {lines};
            elseif isstring(lines)
                app.TextArea.Value = cellstr(lines);
            else
                app.TextArea.Value = lines;
            end
            drawnow limitrate;
        end
        
        

        function resumeBackgroundSavedData(app, S, savedPath)
            
            % =========================================================
            % 1. VALIDATE REQUIRED RAMAN DATA
            % =========================================================
            
            if ~isfield(S, 'RamanDataBackground')
                
                error( ...
                    'The selected MAT file has no RamanDataBackground.');
            end
            
            R = S.RamanDataBackground;
            
            if ~isstruct(R) || ...
                    ~isfield(R, 'Cube') || ...
                    isempty(R.Cube)
                
                error( ...
                    ['RamanDataBackground does not contain a valid ' ...
                    'Raman cube.']);
            end
            
            app.RamanData = R;
            
            app.CubeOriginal = ...
                double(R.Cube);
            
            app.CorrectedCube = ...
                double(R.Cube);
            
            app.BackgroundInputCube = ...
                double(R.Cube);
            
            % =========================================================
            % 2. WAVENUMBER
            % =========================================================
            
            if isfield(R, 'Wavenumber') && ...
                    ~isempty(R.Wavenumber)
                
                app.Wavenumber = ...
                    double(R.Wavenumber(:));
                
            else
                
                app.Wavenumber = ...
                    (1:size(app.CubeOriginal, 3)).';
            end
            
            [ny, nx, nWn] = ...
                size(app.CubeOriginal);
            
            if numel(app.Wavenumber) ~= nWn
                
                error( ...
                    ['The saved Background wavenumber axis does not ' ...
                    'match the Raman cube.']);
            end
            
            % =========================================================
            % 3. SCAN METADATA
            % =========================================================
            
            if isfield(S, 'ProcessingInfo') && ...
                    isfield(S.ProcessingInfo, 'ScanName') && ...
                    ~isempty(S.ProcessingInfo.ScanName)
                
                app.CurrentScanName = ...
                    char(S.ProcessingInfo.ScanName);
                
            else
                
                [~, nm] = fileparts(savedPath);
                
                app.CurrentScanName = nm;
            end
            
            app.CurrentMatPath = savedPath;
            app.BackgroundLastSavedPath = savedPath;
            
            % =========================================================
            % 4. RESTORE ARTIFACT-LEVEL MASKS
            % =========================================================
            
            if isfield(S, 'ArtifactMask2D') && ...
                    isequal(size(S.ArtifactMask2D), [ny nx])
                
                app.BackgroundArtifactMask2D = ...
                    logical(S.ArtifactMask2D);
                
                app.ArtifactMask2D = ...
                    logical(S.ArtifactMask2D);
                
            else
                
                app.BackgroundArtifactMask2D = ...
                    false(ny, nx);
                
                app.ArtifactMask2D = ...
                    false(ny, nx);
            end
            
            if isfield(S, 'ValidPixelMask2D') && ...
                    isequal(size(S.ValidPixelMask2D), [ny nx])
                
                app.BackgroundValidMask2D = ...
                    logical(S.ValidPixelMask2D);
                
                app.ValidPixelMask2D = ...
                    logical(S.ValidPixelMask2D);
                
            else
                
                app.BackgroundValidMask2D = ...
                    all(isfinite(app.BackgroundInputCube), 3) & ...
                    ~app.BackgroundArtifactMask2D;
                
                app.ValidPixelMask2D = ...
                    app.BackgroundValidMask2D;
            end
            
            % =========================================================
            % 5. RESTORE FINAL CLASS MASKS
            % =========================================================
            
            if isfield(S, 'CellMask2D') && ...
                    isequal(size(S.CellMask2D), [ny nx])
                
                app.BackgroundCellMask2D = ...
                    logical(S.CellMask2D);
                
            else
                
                app.BackgroundCellMask2D = ...
                    false(ny, nx);
            end
            
            if isfield(S, 'BackgroundMask2D') && ...
                    isequal(size(S.BackgroundMask2D), [ny nx])
                
                app.BackgroundBackgroundMask2D = ...
                    logical(S.BackgroundMask2D);
                
            else
                
                app.BackgroundBackgroundMask2D = ...
                    false(ny, nx);
            end
            
            if isfield(S, 'UncertainMask2D') && ...
                    isequal(size(S.UncertainMask2D), [ny nx])
                
                app.BackgroundUncertainMask2D = ...
                    logical(S.UncertainMask2D);
                
            else
                
                app.BackgroundUncertainMask2D = ...
                    false(ny, nx);
            end
            
            assignedMask = ...
                app.BackgroundCellMask2D | ...
                app.BackgroundBackgroundMask2D | ...
                app.BackgroundUncertainMask2D;
            
            app.BackgroundUnassignedMask2D = ...
                app.BackgroundValidMask2D & ...
                ~assignedMask;
            
            % =========================================================
            % 6. RESTORE LABEL, CONFIDENCE, MANUAL EDIT
            % =========================================================
            
            if isfield(S, 'ClusterLabelMap') && ...
                    isequal(size(S.ClusterLabelMap), [ny nx])
                
                app.BackgroundLabelMap = ...
                    double(S.ClusterLabelMap);
                
            else
                
                app.BackgroundLabelMap = ...
                    zeros(ny, nx);
            end
            
            if isfield(S, 'ConfidenceMap') && ...
                    isequal(size(S.ConfidenceMap), [ny nx])
                
                app.BackgroundConfidenceMap = ...
                    double(S.ConfidenceMap);
                
            else
                
                app.BackgroundConfidenceMap = ...
                    zeros(ny, nx);
            end
            
            if isfield(S, 'ManualClassMap') && ...
                    isequal(size(S.ManualClassMap), [ny nx])
                
                app.BackgroundManualClassMap = ...
                    uint8(S.ManualClassMap);
                
            else
                
                app.BackgroundManualClassMap = ...
                    zeros(ny, nx, 'uint8');
            end
            
            % =========================================================
            % 7. RESTORE CLUSTER STATE
            % =========================================================
            
            if isfield(S, 'ClusterState') && ...
                    isstruct(S.ClusterState)
                
                app.BackgroundClusters = ...
                    app.normalizeBackgroundClusterStruct( ...
                    S.ClusterState);
                
            else
                
                app.BackgroundClusters = ...
                    app.makeEmptyBackgroundClusters();
            end
            if isempty(app.BackgroundClusters)
                
                app.BackgroundNextClusterID = 1;
                
            else
                
                allIDs = [app.BackgroundClusters.ID];
                
                app.BackgroundNextClusterID = ...
                    max(allIDs) + 1;
            end
            
            % =========================================================
            % 8. RESTORE SEGMENTATION PARAMETERS
            % =========================================================
            
            if isfield(S, 'SegmentationParams') && ...
                    isstruct(S.SegmentationParams)
                
                P = S.SegmentationParams;
                
                if isfield(P, 'Method')
                    app.BackgroundMethod = char(P.Method);
                end
                
                if isfield(P, 'Morphology')
                    app.BackgroundMorphology = char(P.Morphology);
                end
                
                if isfield(P, 'K')
                    app.BackgroundK = double(P.K);
                end
                
                if isfield(P, 'VarianceThreshold')
                    app.BackgroundVarianceThreshold = ...
                        double(P.VarianceThreshold);
                end
                
                if isfield(P, 'MaxPC')
                    app.BackgroundMaxPC = double(P.MaxPC);
                end
                
                if isfield(P, 'NumPCUsed')
                    app.BackgroundNumPCUsed = ...
                        double(P.NumPCUsed);
                end
                
                if isfield(P, 'ExplainedVariance')
                    app.BackgroundExplained = ...
                        double(P.ExplainedVariance);
                end
                
                if isfield(P, 'Replicates')
                    app.BackgroundReplicates = ...
                        double(P.Replicates);
                end
                
                if isfield(P, 'RandomSeed')
                    app.BackgroundRandomSeed = ...
                        double(P.RandomSeed);
                end
                
                if isfield(P, 'TrainingMaxSpectra')
                    app.BackgroundTrainingMaxSpectra = ...
                        double(P.TrainingMaxSpectra);
                end
            end
            
            % =========================================================
            % 9. RESTORE SCIENTIFIC METRICS
            % =========================================================
            
            if isfield(S, 'GlobalClusterMetrics')
                app.BackgroundGlobalMetrics = ...
                    S.GlobalClusterMetrics;
            else
                app.BackgroundGlobalMetrics = struct();
            end
            
            if isfield(S, 'ClusterMetrics')
                app.BackgroundClusterMetrics = ...
                    S.ClusterMetrics;
            else
                app.BackgroundClusterMetrics = ...
                    app.BackgroundClusterMetrics([]);
            end
            
            if isfield(S, 'SilhouetteValues')
                app.BackgroundSilhouetteValues = ...
                    double(S.SilhouetteValues);
            end
            
            if isfield(S, 'PixelStability')
                app.BackgroundPixelStability = ...
                    double(S.PixelStability);
            end
            
            if isfield(S, 'MetricPixelIdx')
                app.BackgroundMetricPixelIdx = ...
                    double(S.MetricPixelIdx);
            end
            
            if isfield(S, 'MetricLabels')
                app.BackgroundMetricLabels = ...
                    double(S.MetricLabels);
            end
            
            if isfield(S, 'PCAScores')
                app.BackgroundAllScores = ...
                    double(S.PCAScores);
            end
            
            if isfield(S, 'PCACoefficients')
                app.BackgroundPCAcoeff = ...
                    double(S.PCACoefficients);
            end
            
            if isfield(S, 'PCAMean')
                app.BackgroundPCAmu = ...
                    double(S.PCAMean);
            end
            
            if isfield(S, 'ClusterCentroids')
                app.BackgroundCentroids = ...
                    double(S.ClusterCentroids);
            end
            
            if isfield(S, 'TrainingLocalIdx')
                app.BackgroundTrainingLocalIdx = ...
                    double(S.TrainingLocalIdx);
            end
            
            if isfield(S, 'TrainingLinearIdx')
                app.BackgroundTrainingLinearIdx = ...
                    double(S.TrainingLinearIdx);
            end
            
            if isfield(S, 'LastSplitTrial')
                app.BackgroundLastSplitTrial = ...
                    S.LastSplitTrial;
            end
            
            % =========================================================
            % 10. BUILD PREVIEW MAP
            %
            % buildBackgroundPreviewMap reads:
            %   app.BackgroundInputCube
            %   app.Wavenumber
            %   app.BackgroundValidMask2D
            %
            % Therefore it must be called without an extra cube input.
            % =========================================================
            
            if isempty(app.BackgroundInputCube)
                
                error( ...
                    ['Cannot rebuild the Background preview because ' ...
                    'BackgroundInputCube is empty.']);
            end
            
            if isempty(app.BackgroundValidMask2D) || ...
                    ~isequal( ...
                    size(app.BackgroundValidMask2D), ...
                    [ny nx])
                
                app.BackgroundValidMask2D = ...
                    all(isfinite(app.BackgroundInputCube), 3) & ...
                    ~app.BackgroundArtifactMask2D;
                
                app.ValidPixelMask2D = ...
                    app.BackgroundValidMask2D;
            end
            
            [previewMap, previewLabel] = ...
                app.buildBackgroundPreviewMap();
            
            app.BackgroundPreviewMap = ...
                previewMap;
            
            app.BackgroundPreviewLabel = ...
                previewLabel;
            % =========================================================
            % 11. RESTORE UI SETTINGS
            % =========================================================
            
            if any(strcmp( ...
                    app.SegmethodDropDown.Items, ...
                    app.BackgroundMethod))
                
                app.SegmethodDropDown.Value = ...
                    app.BackgroundMethod;
            end
            
            if any(strcmp( ...
                    app.MorphologyDropDown.Items, ...
                    app.BackgroundMorphology))
                
                app.MorphologyDropDown.Value = ...
                    app.BackgroundMorphology;
            end
            
            % =========================================================
            % 12. FINAL STATE
            % =========================================================
            
            hasArtifactMasks = ...
                ~isempty(app.ArtifactMask2D) && ...
                ~isempty(app.ValidPixelMask2D) && ...
                isequal(size(app.ArtifactMask2D), [ny nx]) && ...
                isequal(size(app.ValidPixelMask2D), [ny nx]);
            
            app.ArtifactReady = hasArtifactMasks;
            app.ArtifactConfirmed = hasArtifactMasks;
            
            hasCosmicReviewState = ...
                ~isempty(app.CandidateTable) || ...
                ~isempty(app.ConfirmedMask);
            
            app.CorrectionReady = hasCosmicReviewState;
            app.CosmicConfirmed = hasCosmicReviewState;
            
            
            % The loaded MAT file is a complete Background-stage
            % snapshot and may be edited independently.
            app.BackgroundReady = true;
            app.BackgroundConfirmed = true;
            app.BackgroundLastSavedPath = savedPath;
            
            [isValid, ~] = ...
                app.validateBackgroundResult();
            
            app.BackgroundValidated = isValid;
            
            % Recalculate only when the old file contains no saved metric.
            if isempty(fieldnames(app.BackgroundGlobalMetrics)) && ...
                    numel(app.BackgroundClusters) >= 2
                
                try
                    app.computeBackgroundScientificMetrics();
                catch ME
                    warning( ...
                        'RamanCube:BackgroundMetricRestoreFailed', ...
                        '%s', ...
                        ME.message);
                end
            end
            
            % =========================================================
            % 13. REBUILD DISPLAY
            % =========================================================
            
            app.TabGroup.SelectedTab = ...
                app.BackgroundremovalTab;
            
            app.populateBackgroundTree();
            app.updateBackgroundInfo();
            app.updateBackgroundQC();
            app.displayBackgroundView();
            % A saved Background file is a self-contained Background
            % snapshot. Cosmic and Artifact UI states are not required
            % for continued Background editing.
            app.setBackgroundControlsEnabled(true);
            
            app.TextArea_3.Value = { ...
                'RESUMED BACKGROUND SAVED DATA'; ...
                '================================'; ...
                ''; ...
                ['Saved file: ' savedPath]; ...
                ['Scan: ' app.CurrentScanName]; ...
                sprintf('Cell pixels: %d', ...
                nnz(app.BackgroundCellMask2D)); ...
                sprintf('Background pixels: %d', ...
                nnz(app.BackgroundBackgroundMask2D)); ...
                sprintf('Uncertain pixels: %d', ...
                nnz(app.BackgroundUncertainMask2D)); ...
                sprintf('Unassigned pixels: %d', ...
                nnz(app.BackgroundUnassignedMask2D)); ...
                ''; ...
                'Clusters, masks, metrics, and QC were restored.'; ...
                ''; ...
                'Background editing is enabled.'; ...
                'Cosmic and Artifact do not need to be rerun.'; ...
                'Their saved outputs are already represented by'; ...
                'the active cube and artifact-valid pixel mask.'};
        end
        
        function resetSpectrumAxis(app,ax)
            cla(ax,'reset');
            ax.Box = 'on';
            ax.XGrid = 'off';
            ax.YGrid = 'off';
            ax.YDir = 'normal';
            ax.YAxisLocation = 'right';
            ax.DataAspectRatioMode = 'auto';
            ax.PlotBoxAspectRatioMode = 'auto';
            ax.XTickMode = 'auto';
            ax.YTickMode = 'auto';
            ax.XLimMode = 'auto';
            ax.YLimMode = 'auto';
            ax.FontSize = 10;
            ax.TickDir = 'in';
            ax.Layer = 'top';
            xlabel(ax,'Wavenumber (cm^{-1})');
            ylabel(ax,'Intensity');
            ax.XLabel.Units = 'normalized';
            ax.XLabel.Position = [0.5 -0.075 0];
        end
        
        function resetImageAxis(app, ax)
            
            cla(ax, 'reset');
            
            ax.Box = 'on';
            ax.XGrid = 'off';
            ax.YGrid = 'off';
            
            % Image-coordinate convention:
            % cube row 1 is displayed at the top, consistently with Loadscan,
            % imshow, trace images, and pixel row indexing.
            ax.YDir = 'reverse';
            
            ax.XTickMode = 'auto';
            ax.YTickMode = 'auto';
            ax.FontSize = 10;
            ax.TickDir = 'in';
            ax.Layer = 'top';
            
            ax.LooseInset = ...
                max(ax.TightInset, [0.08 0.06 0.03 0.03]);
        end
        
        function fixAxisMargin(app, ax, isSpectrum)
            
            drawnow limitrate;
            
            ax.Box = 'on';
            ax.TickDir = 'in';
            ax.Layer = 'top';
            ax.FontSize = 10;
            
            if isSpectrum
                
                % Spectral axes use the normal Cartesian convention.
                ax.YDir = 'normal';
                ax.YAxisLocation = 'right';
                ax.DataAspectRatioMode = 'auto';
                ax.PlotBoxAspectRatioMode = 'auto';
                
                xlabel(ax, 'Wavenumber (cm^{-1})');
                ylabel(ax, 'Intensity');
                
                ax.XLabel.Units = 'normalized';
                ax.XLabel.Position = [0.5 -0.075 0];
                
                ax.YTickLabelMode = 'auto';
                ax.YTickMode = 'auto';
                
            else
                
                % Image/map axes use image coordinates:
                % row 1 remains at the top.
                ax.YDir = 'reverse';
                axis(ax, 'image');
            end
        end
        
        function setNiceSpectralLimits(app,ax,xVals,yVals)
            xVals = double(xVals(:));
            yVals = double(yVals(:));
            xVals = xVals(isfinite(xVals));
            yVals = yVals(isfinite(yVals));
            if ~isempty(xVals)
                xmin = min(xVals);
                xmax = max(xVals);
                if xmin < xmax
                    xlim(ax,[xmin xmax]);
                end
            end
            if isempty(yVals)
                return
            end
            yMin = min(yVals);
            yMax = max(yVals);
            p01 = prctile(yVals,1);
            p99 = prctile(yVals,99);
            if p01 == p99
                p01 = yMin;
                p99 = yMax;
            end
            lower = p01;
            upper = p99;
            if yMax > p99
                upper = yMax;
            end
            rangeBase = p99 - p01;
            if rangeBase <= eps || isnan(rangeBase)
                rangeBase = yMax - yMin;
            end
            if rangeBase <= eps || isnan(rangeBase)
                rangeBase = 1;
            end
            padLow = 0.08*rangeBase;
            padHigh = 0.10*(upper-lower);
            if padHigh <= eps || isnan(padHigh)
                padHigh = 1;
            end
            ylim(ax,[lower-padLow upper+padHigh]);
            ax.YAxisLocation = 'right';
            ax.YTickLabelMode = 'auto';
            ax.YTickMode = 'auto';
        end
        
        function imageHandle = showMapOnNormalAxes(app, ax, mapImg)
            
            if nargin < 3 || isempty(mapImg)
                
                cla(ax, 'reset');
                
                ax.Box = 'on';
                ax.YDir = 'reverse';
                
                imageHandle = ...
                    matlab.graphics.primitive.Image.empty;
                
                return
            end
            
            mapImg = ...
                double(mapImg);
            
            imageHandle = ...
                imagesc(ax, mapImg);
            
            imageHandle.HitTest = ...
                'off';
            
            imageHandle.PickableParts = ...
                'none';
            
            axis(ax, 'image');
            
            % Keep the same spatial convention as Loadscan and imshow.
            ax.YDir = ...
                'reverse';
            
            ax.Box = ...
                'on';
            
            ax.Layer = ...
                'top';
            
            ax.XLim = ...
                [0.5, size(mapImg, 2) + 0.5];
            
            ax.YLim = ...
                [0.5, size(mapImg, 1) + 0.5];
            
            ax.XTickMode = ...
                'auto';
            
            ax.YTickMode = ...
                'auto';
        end
        
        function exportAxisImage(app,ax,outFile)
            drawnow;
            exportgraphics(ax,outFile,'Resolution',300);
        end
        
        function forceInsideYAxis(app,ax)
            drawnow limitrate;
            ax.YDir = 'normal';
            ax.Box = 'on';
            ax.TickDir = 'in';
            ax.Layer = 'top';
            ax.FontSize = 10;
            yt = ax.YTick;
            yl = ax.YLim;
            xl = ax.XLim;
            if isempty(yt) || numel(xl) < 2 || xl(1) == xl(2) || yl(1) == yl(2)
                return
            end
            delete(findall(ax,'Tag','InsideYAxisLabel'));
            delete(findall(ax,'Tag','InsideYTickLabel'));
            ax.YTickLabel = [];
            ylabel(ax,'');
            xText = xl(1) + 0.012*(xl(2)-xl(1));
            for i = 1:numel(yt)
                if yt(i) < yl(1) || yt(i) > yl(2)
                    continue
                end
                text(ax,xText,yt(i),sprintf('%.4g',yt(i)),...
                    'Tag','InsideYTickLabel',...
                    'FontSize',9,...
                    'Color',[0.05 0.05 0.05],...
                    'HorizontalAlignment','left',...
                    'VerticalAlignment','middle',...
                    'Clipping','on',...
                    'BackgroundColor','w',...
                    'Margin',0.5);
            end
            text(ax,xl(1)+0.018*(xl(2)-xl(1)),yl(1)+0.5*(yl(2)-yl(1)),'Intensity',...
                'Tag','InsideYAxisLabel',...
                'FontSize',10,...
                'FontWeight','normal',...
                'Color',[0.05 0.05 0.05],...
                'Rotation',90,...
                'HorizontalAlignment','center',...
                'VerticalAlignment','bottom',...
                'Clipping','on',...
                'BackgroundColor','w',...
                'Margin',1);
        end
        
        
        %% ============================================================
        %  ARTIFACT TAB INITIALIZATION AND UI HELPERS
        %% ============================================================
        
        function initializeArtifactTab(app)
            
            app.ModeDropDown.Items = { ...
                '1 | Light detection', ...
                '2 | Dense single horizontal lines', ...
                '3 | Dense adjacent horizontal bands', ...
                '4 | Strong complex detection', ...
                '5 | Manual full parameter editing', ...
                '6 | Safe recommended'};
            
            app.ModeDropDown.Value = '6 | Safe recommended';
            
            app.ViewDropDown.Items = { ...
                'Interpolated clean map', ...
                'Masked artifact as black'};
            
            app.ViewDropDown.Value = 'Interpolated clean map';
            app.ViewDropDown.Tooltip = sprintf([ ...
                'Artifact display mode:\n', ...
                'Interpolated clean map: Axis 2 shows display-only spectral-guided inpainted map.\n', ...
                'Masked artifact as black: Axis 2 shows raw map with detected artifact pixels in black.']);
            
            app.ColorsDropDown.Items = { ...
                'Turbo', ...
                'Parula', ...
                'Gray', ...
                'Hot', ...
                'Jet', ...
                'Bone', ...
                'Copper', ...
                'Pink'};
            
            app.ColorsDropDown.Value = 'Hot';
            app.ColorsDropDown.Tooltip = 'Choose colormap for artifact display maps.';
            
            app.ArtifactModeName = 'Safe recommended';
            app.ArtifactViewMode = app.ViewDropDown.Value;
            app.ArtifactColorMap = app.ColorsDropDown.Value;
            
            app.ArtifactParams = app.getArtifactPresetParams(app.ModeDropDown.Value);
            
            app.ArtifactReady = false;
            app.ArtifactConfirmed = false;
            app.ArtifactMask2D = logical.empty;
            app.ValidPixelMask2D = logical.empty;
            app.ArtifactDisplayMapRaw = [];
            app.ArtifactDisplayMapClean = [];
            app.ArtifactDisplayLabel = '';
            
            app.setArtifactText(app.makeArtifactParameterText(app.ArtifactParams, app.ModeDropDown.Value, false));
            
            app.resetImageAxis(app.UIAxes3);
            app.resetImageAxis(app.UIAxes4);
            title(app.UIAxes3,'Axis 1');
            title(app.UIAxes4,'Axis 2');
        end
        
        
        function setArtifactText(app,lines)
            
            if ischar(lines)
                app.TextArea_2.Value = {lines};
            elseif isstring(lines)
                app.TextArea_2.Value = cellstr(lines);
            else
                app.TextArea_2.Value = lines;
            end
            
            drawnow limitrate;
        end
        
        
        function params = getArtifactPresetParams(app,modeValue)
            
            params = struct();
            
            % Common display + inpainting defaults.
            params.DisplayBandLow = 2840;
            params.DisplayBandHigh = 2940;
            
            params.InpaintRadiusY = 8;
            params.InpaintRadiusX = 8;
            params.InpaintMinNeighbors = 12;
            params.InpaintMaxNeighbors = 100;
            params.InpaintSpatialSigmaY = 3.5;
            params.InpaintSpatialSigmaX = 5.0;
            params.InpaintSpectralSigma = 0.22;
            params.InpaintIntensitySigma = 0.35;
            params.InpaintReferenceBandLow = 600;
            params.InpaintReferenceBandHigh = 1800;
            params.InpaintUseWeightedMedian = false;
            params.InpaintMinConfidence = 0.08;
            
            modeValue = char(modeValue);
            
            if startsWith(modeValue,'1')
                % Light detection: fewer false positives, for simple clear stripes.
                params.ModeLabel = '1 | Light detection';
                params.Description = 'Light detection for simple, clear, sparse horizontal artifacts.';
                params.MedianFilterHeight = 7;
                params.StrongZ = 9.5;
                params.WeakZ = 4.2;
                params.MinWidth = 35;
                params.MaxHeight = 45;
                params.MinAspect = 3.0;
                params.MinArea = 40;
                params.HorizontalBridge = 15;
                params.FinalHorizontalBridge = 15;
                params.VerticalGrowRadius = 1;
                params.RowZ = 6.0;
                params.MinRowFraction = 0.35;
                params.SpectralZ = 2.8;
                params.MinBandConsistency = 4;
                params.RemoveBorderTouching = false;
                
            elseif startsWith(modeValue,'2')
                % Dense but mostly separated single lines.
                params.ModeLabel = '2 | Dense single horizontal lines';
                params.Description = 'For many crowded horizontal artifacts, mostly separated as individual thin lines.';
                params.MedianFilterHeight = 9;
                params.StrongZ = 7.5;
                params.WeakZ = 3.2;
                params.MinWidth = 18;
                params.MaxHeight = 55;
                params.MinAspect = 2.0;
                params.MinArea = 15;
                params.HorizontalBridge = 28;
                params.FinalHorizontalBridge = 28;
                params.VerticalGrowRadius = 1;
                params.RowZ = 4.8;
                params.MinRowFraction = 0.18;
                params.SpectralZ = 1.8;
                params.MinBandConsistency = 2;
                params.RemoveBorderTouching = false;
                
            elseif startsWith(modeValue,'3')
                % Dense adjacent horizontal bands.
                params.ModeLabel = '3 | Dense adjacent horizontal bands';
                params.Description = 'For crowded artifacts where multiple horizontal lines are adjacent or partly merged.';
                params.MedianFilterHeight = 9;
                params.StrongZ = 7.0;
                params.WeakZ = 3.0;
                params.MinWidth = 18;
                params.MaxHeight = 90;
                params.MinAspect = 1.5;
                params.MinArea = 15;
                params.HorizontalBridge = 30;
                params.FinalHorizontalBridge = 30;
                params.VerticalGrowRadius = 2;
                params.RowZ = 4.2;
                params.MinRowFraction = 0.16;
                params.SpectralZ = 1.6;
                params.MinBandConsistency = 2;
                params.RemoveBorderTouching = false;
                
            elseif startsWith(modeValue,'4')
                % Strong complex detection.
                params.ModeLabel = '4 | Strong complex detection';
                params.Description = 'Strong complex detection for weak, thick, interrupted or cell-hidden stripe artifacts.';
                params.MedianFilterHeight = 11;
                params.StrongZ = 6.5;
                params.WeakZ = 2.8;
                params.MinWidth = 15;
                params.MaxHeight = 110;
                params.MinAspect = 1.3;
                params.MinArea = 12;
                params.HorizontalBridge = 40;
                params.FinalHorizontalBridge = 35;
                params.VerticalGrowRadius = 3;
                params.RowZ = 3.8;
                params.MinRowFraction = 0.12;
                params.SpectralZ = 1.4;
                params.MinBandConsistency = 2;
                params.RemoveBorderTouching = false;
                
            elseif startsWith(modeValue,'5')
                % Manual starts from safe recommended.
                params = app.getArtifactPresetParams('6 | Safe recommended');
                params.ModeLabel = '5 | Manual full parameter editing';
                params.Description = 'Manual mode. Starts from safe recommended, then opens a full parameter editor.';
                
            else
                % Safe recommended: current good script values.
                params.ModeLabel = '6 | Safe recommended';
                params.Description = 'Safe recommended preset. This is the validated balanced setting from the current script.';
                params.MedianFilterHeight = 9;
                params.StrongZ = 8.0;
                params.WeakZ = 3.5;
                params.MinWidth = 25;
                params.MaxHeight = 60;
                params.MinAspect = 2.0;
                params.MinArea = 20;
                params.HorizontalBridge = 20;
                params.FinalHorizontalBridge = 20;
                params.VerticalGrowRadius = 1;
                params.RowZ = 5.0;
                params.MinRowFraction = 0.25;
                params.SpectralZ = 2.0;
                params.MinBandConsistency = 3;
                params.RemoveBorderTouching = false;
            end
            
            params = app.sanitizeArtifactParams(params);
        end
        
        
        function params = editArtifactParametersManual(app,params)
            
            prompts = { ...
                'Display band low, cm^-1', ...
                'Display band high, cm^-1', ...
                'Vertical median filter height, odd', ...
                'Strong stripe Z threshold', ...
                'Weak stripe Z threshold', ...
                'Minimum horizontal width, pixels', ...
                'Maximum stripe/band height, pixels', ...
                'Minimum horizontal aspect ratio', ...
                'Minimum connected area, pixels', ...
                'Horizontal bridge before reconstruction, pixels', ...
                'Final horizontal bridge after reconstruction, pixels', ...
                'Vertical grow radius, pixels', ...
                'Row evidence Z threshold', ...
                'Minimum abnormal fraction per row, 0-1', ...
                'Spectral neighbor Z support threshold', ...
                'Minimum band consistency count', ...
                'Remove border-touching components? 0=no, 1=yes', ...
                'Display inpaint RadiusY', ...
                'Display inpaint RadiusX', ...
                'Display inpaint MinNeighbors', ...
                'Display inpaint MaxNeighbors', ...
                'Display inpaint SpectralSigma', ...
                'Display inpaint IntensitySigma'};
            
            defaults = { ...
                num2str(params.DisplayBandLow), ...
                num2str(params.DisplayBandHigh), ...
                num2str(params.MedianFilterHeight), ...
                num2str(params.StrongZ), ...
                num2str(params.WeakZ), ...
                num2str(params.MinWidth), ...
                num2str(params.MaxHeight), ...
                num2str(params.MinAspect), ...
                num2str(params.MinArea), ...
                num2str(params.HorizontalBridge), ...
                num2str(params.FinalHorizontalBridge), ...
                num2str(params.VerticalGrowRadius), ...
                num2str(params.RowZ), ...
                num2str(params.MinRowFraction), ...
                num2str(params.SpectralZ), ...
                num2str(params.MinBandConsistency), ...
                num2str(double(params.RemoveBorderTouching)), ...
                num2str(params.InpaintRadiusY), ...
                num2str(params.InpaintRadiusX), ...
                num2str(params.InpaintMinNeighbors), ...
                num2str(params.InpaintMaxNeighbors), ...
                num2str(params.InpaintSpectralSigma), ...
                num2str(params.InpaintIntensitySigma)};
            
            answ = inputdlg(prompts,'Manual artifact parameters',[1 110],defaults);
            
            if isempty(answ)
                params = [];
                return
            end
            
            params.DisplayBandLow = str2double(answ{1});
            params.DisplayBandHigh = str2double(answ{2});
            params.MedianFilterHeight = round(str2double(answ{3}));
            params.StrongZ = str2double(answ{4});
            params.WeakZ = str2double(answ{5});
            params.MinWidth = round(str2double(answ{6}));
            params.MaxHeight = round(str2double(answ{7}));
            params.MinAspect = str2double(answ{8});
            params.MinArea = round(str2double(answ{9}));
            params.HorizontalBridge = round(str2double(answ{10}));
            params.FinalHorizontalBridge = round(str2double(answ{11}));
            params.VerticalGrowRadius = round(str2double(answ{12}));
            params.RowZ = str2double(answ{13});
            params.MinRowFraction = str2double(answ{14});
            params.SpectralZ = str2double(answ{15});
            params.MinBandConsistency = round(str2double(answ{16}));
            params.RemoveBorderTouching = logical(round(str2double(answ{17})));
            
            params.InpaintRadiusY = round(str2double(answ{18}));
            params.InpaintRadiusX = round(str2double(answ{19}));
            params.InpaintMinNeighbors = round(str2double(answ{20}));
            params.InpaintMaxNeighbors = round(str2double(answ{21}));
            params.InpaintSpectralSigma = str2double(answ{22});
            params.InpaintIntensitySigma = str2double(answ{23});
            
            params = app.sanitizeArtifactParams(params);
        end
        
        
        function params = sanitizeArtifactParams(app,params)
            
            if isnan(params.DisplayBandLow)
                params.DisplayBandLow = 2840;
            end
            
            if isnan(params.DisplayBandHigh)
                params.DisplayBandHigh = 2940;
            end
            
            if params.DisplayBandLow > params.DisplayBandHigh
                tmp = params.DisplayBandLow;
                params.DisplayBandLow = params.DisplayBandHigh;
                params.DisplayBandHigh = tmp;
            end
            
            params.MedianFilterHeight = max(3,round(params.MedianFilterHeight));
            if mod(params.MedianFilterHeight,2)==0
                params.MedianFilterHeight = params.MedianFilterHeight + 1;
            end
            
            params.StrongZ = max(0.5,params.StrongZ);
            params.WeakZ = max(0.1,params.WeakZ);
            
            if params.WeakZ > params.StrongZ
                tmp = params.WeakZ;
                params.WeakZ = params.StrongZ;
                params.StrongZ = tmp;
            end
            
            params.MinWidth = max(3,round(params.MinWidth));
            params.MaxHeight = max(1,round(params.MaxHeight));
            params.MinAspect = max(1,params.MinAspect);
            params.MinArea = max(1,round(params.MinArea));
            
            params.HorizontalBridge = max(1,round(params.HorizontalBridge));
            params.FinalHorizontalBridge = max(1,round(params.FinalHorizontalBridge));
            params.VerticalGrowRadius = max(0,round(params.VerticalGrowRadius));
            
            params.RowZ = max(0.1,params.RowZ);
            params.MinRowFraction = min(max(params.MinRowFraction,0),1);
            params.SpectralZ = max(0.1,params.SpectralZ);
            params.MinBandConsistency = max(1,round(params.MinBandConsistency));
            
            params.InpaintRadiusY = max(1,round(params.InpaintRadiusY));
            params.InpaintRadiusX = max(1,round(params.InpaintRadiusX));
            params.InpaintMinNeighbors = max(3,round(params.InpaintMinNeighbors));
            params.InpaintMaxNeighbors = max(params.InpaintMinNeighbors,round(params.InpaintMaxNeighbors));
            params.InpaintSpatialSigmaY = max(0.1,params.InpaintSpatialSigmaY);
            params.InpaintSpatialSigmaX = max(0.1,params.InpaintSpatialSigmaX);
            params.InpaintSpectralSigma = max(0.01,params.InpaintSpectralSigma);
            params.InpaintIntensitySigma = max(0.01,params.InpaintIntensitySigma);
            params.InpaintMinConfidence = min(max(params.InpaintMinConfidence,0),1);
            
            if ~isfield(params,'ModeLabel')
                params.ModeLabel = 'Custom';
            end
            
            if ~isfield(params,'Description')
                params.Description = 'Custom artifact parameters.';
            end
        end
        
        
        function lines = makeArtifactParameterText(app,p,modeValue,afterDetection)
            
            if nargin < 4
                afterDetection = false;
            end
            
            lines = { ...
                'ARTIFACT REMOVAL PARAMETERS'; ...
                '================================'; ...
                ''; ...
                ['Mode: ' char(modeValue)]; ...
                ['Description: ' char(p.Description)]; ...
                ''; ...
                'Display band:'; ...
                sprintf('  %.0f-%.0f cm^-1',p.DisplayBandLow,p.DisplayBandHigh); ...
                ''; ...
                'Detection core:'; ...
                sprintf('  MedianFilterHeight: %d',p.MedianFilterHeight); ...
                sprintf('  StrongZ: %.2f',p.StrongZ); ...
                sprintf('  WeakZ: %.2f',p.WeakZ); ...
                sprintf('  MinWidth: %d px',p.MinWidth); ...
                sprintf('  MaxHeight: %d px',p.MaxHeight); ...
                sprintf('  MinAspect: %.2f',p.MinAspect); ...
                sprintf('  MinArea: %d px',p.MinArea); ...
                ''; ...
                'Bridge / grow:'; ...
                sprintf('  HorizontalBridge: %d px',p.HorizontalBridge); ...
                sprintf('  FinalHorizontalBridge: %d px',p.FinalHorizontalBridge); ...
                sprintf('  VerticalGrowRadius: %d px',p.VerticalGrowRadius); ...
                ''; ...
                'Spatial-spectral support:'; ...
                sprintf('  RowZ: %.2f',p.RowZ); ...
                sprintf('  MinRowFraction: %.2f',p.MinRowFraction); ...
                sprintf('  SpectralZ: %.2f',p.SpectralZ); ...
                sprintf('  MinBandConsistency: %d',p.MinBandConsistency); ...
                sprintf('  RemoveBorderTouching: %d',double(p.RemoveBorderTouching)); ...
                ''; ...
                'Display-only inpainting:'; ...
                sprintf('  RadiusY / RadiusX: %d / %d',p.InpaintRadiusY,p.InpaintRadiusX); ...
                sprintf('  MinNeighbors / MaxNeighbors: %d / %d',p.InpaintMinNeighbors,p.InpaintMaxNeighbors); ...
                sprintf('  SpectralSigma: %.2f',p.InpaintSpectralSigma); ...
                sprintf('  IntensitySigma: %.2f',p.InpaintIntensitySigma); ...
                ''; ...
                'Important:'; ...
                '  Clean map is for visualization only.'; ...
                '  Downstream extraction uses ValidPixelMask2D.'};
            
            if afterDetection
                lines = [lines; { ...
                    ''; ...
                    'DETECTION RESULT'; ...
                    '================================'; ...
                    sprintf('Artifact pixels: %d',nnz(app.ArtifactMask2D)); ...
                    sprintf('Valid pixels: %d',nnz(app.ValidPixelMask2D)); ...
                    sprintf('Artifact percent: %.2f%%',100*nnz(app.ArtifactMask2D)/numel(app.ArtifactMask2D)); ...
                    sprintf('Artifact components: %d',height(app.ArtifactTable)); ...
                    ''; ...
                    'Axis 1: raw display map + artifact overlay.'; ...
                    'Axis 2 depends on View:'; ...
                    '  Interpolated clean map = display-only inpainted map.'; ...
                    '  Masked artifact as black = raw map with artifact pixels in black.'}];
            end
        end
        
        
        function plotArtifactInitialView(app)
            
            if isempty(app.CubeOriginal)
                return
            end
            
            app.ArtifactViewMode = app.ViewDropDown.Value;
            app.ArtifactColorMap = app.ColorsDropDown.Value;
            
            cube = app.getActiveCube();
            p = app.ArtifactParams;
            
            [mapImg,mapLabel] = app.buildArtifactDisplayMap(cube,p.DisplayBandLow,p.DisplayBandHigh);
            
            app.ArtifactDisplayMapRaw = mapImg;
            app.ArtifactDisplayLabel = mapLabel;
            
            app.resetImageAxis(app.UIAxes3);
            app.showArtifactMapOnAxes(app.UIAxes3,mapImg);
            title(app.UIAxes3,{app.CurrentScanName,mapLabel,'Raw map for artifact detection'},'Interpreter','none');
            
            app.resetImageAxis(app.UIAxes4);
            
            if strcmp(app.ArtifactViewMode,'Masked artifact as black') && ~isempty(app.ArtifactMask2D)
                
                app.plotArtifactMaskedBlackAxis( ...
                    app.UIAxes4, ...
                    app.ArtifactDisplayMapRaw, ...
                    app.ArtifactMask2D, ...
                    {'Raw map with detected artifact pixels in black','Mask display only'});
                
            elseif ~isempty(app.ArtifactDisplayMapClean)
                
                app.showArtifactMapOnAxes(app.UIAxes4,app.ArtifactDisplayMapClean);
                title(app.UIAxes4,{'Interpolated clean display map','visualization only - not used for analysis'},'Interpreter','none');
                
            else
                
                app.showArtifactMapOnAxes(app.UIAxes4,mapImg);
                title(app.UIAxes4,'Axis 2 will update after Detect artifact','Interpreter','none');
            end
        end
        
        
        function [mapImg,mapLabel] = buildArtifactDisplayMap(app,cube,bandLow,bandHigh)
            
            wn = app.Wavenumber(:);
            useBand = wn >= bandLow & wn <= bandHigh;
            
            if nnz(useBand) >= 3
                mapImg = mean(cube(:,:,useBand),3,'omitnan');
                mapLabel = sprintf('Mean intensity %.0f-%.0f cm^{-1}',bandLow,bandHigh);
            else
                mapImg = mean(cube,3,'omitnan');
                mapLabel = 'Total mean projection';
            end
            
            mapImg = app.cleanArtifactMap(mapImg);
        end
        
        
        function img = cleanArtifactMap(app,img)
            
            img = double(img);
            vals = img(isfinite(img));
            
            if isempty(vals)
                img = zeros(size(img));
                return
            end
            
            fillVal = median(vals,'omitnan');
            img(~isfinite(img)) = fillVal;
            
            vals = img(isfinite(img));
            
            if isempty(vals)
                img = zeros(size(img));
                return
            end
            
            if max(vals) == min(vals)
                img(:) = fillVal;
            end
        end
        
        
        %% ============================================================
        %  ARTIFACT DETECTOR CORE
        %% ============================================================
        
        function result = runArtifactDetection(app,cube,wn,params)
            
            cube = double(cube);
            wn = double(wn(:));
            
            [ny,nx,~] = size(cube);
            
            [displayMap,displayLabel] = app.buildArtifactDisplayMap(cube,params.DisplayBandLow,params.DisplayBandHigh);
            
            bandDefs = [ ...
                600 1800; ...
                1000 1700; ...
                1200 1500; ...
                2800 3000; ...
                2840 2940];
            
            bandNames = { ...
                '600-1800', ...
                '1000-1700', ...
                '1200-1500', ...
                '2800-3000', ...
                '2840-2940'};
            
            [BandMaps,BandLabels] = app.buildMultipleArtifactBandMaps(cube,wn,bandDefs,bandNames);
            
            TotalMeanMap = mean(cube,3,'omitnan');
            TotalMeanMap = app.cleanArtifactMap(TotalMeanMap);
            
            BandMaps(:,:,end+1) = TotalMeanMap;
            BandLabels{end+1} = 'Total mean projection';
            
            nMaps = size(BandMaps,3);
            ZStack = zeros(ny,nx,nMaps);
            
            for i = 1:nMaps
                ZStack(:,:,i) = app.computeHorizontalStripeResidualZ(BandMaps(:,:,i),params.MedianFilterHeight);
            end
            
            StripeScoreMap = max(ZStack,[],3);
            BandConsistencyMap = sum(ZStack >= params.WeakZ,3);
            
            StrongPixelMask = StripeScoreMap >= params.StrongZ;
            
            WeakPixelMask = ...
                (StripeScoreMap >= params.WeakZ & BandConsistencyMap >= params.MinBandConsistency) | ...
                (StripeScoreMap >= params.WeakZ + 1.5);
            
            StrongRunMask = app.keepHorizontalRuns(StrongPixelMask,params.MinWidth);
            WeakRunMask = app.keepHorizontalRuns(WeakPixelMask,max(3,round(params.MinWidth/2)));
            
            if params.HorizontalBridge > 1
                seH = strel('line',params.HorizontalBridge,0);
                StrongRunMask = imclose(StrongRunMask,seH);
                WeakRunMask = imclose(WeakRunMask,seH);
            end
            
            StrongSeedMask = bwareaopen(StrongRunMask,params.MinArea);
            WeakSupportMask = bwareaopen(WeakRunMask,max(5,round(params.MinArea/2)));
            
            [rowBandMask,rowEvidence] = app.computeArtifactRowBandMask(StripeScoreMap,WeakSupportMask,params);
            
            WeakSupportMask = WeakSupportMask | (WeakPixelMask & rowBandMask);
            
            if params.VerticalGrowRadius > 0
                seV = strel('rectangle',[2*params.VerticalGrowRadius+1,1]);
                StrongSeedMask = imdilate(StrongSeedMask,seV);
                WeakSupportMask = imdilate(WeakSupportMask,seV);
            end
            
            StrongSeedMask = StrongSeedMask & WeakSupportMask;
            
            SpectralZMap = app.computeSpectralNeighborZMapArtifact(cube);
            SpectralSupportMask = SpectralZMap >= params.SpectralZ;
            
            WeakSupportMask = WeakSupportMask | ...
                (WeakPixelMask & rowBandMask & SpectralSupportMask);
            
            WeakSupportMask = bwareaopen(WeakSupportMask,max(5,round(params.MinArea/2)));
            WeakSupportMask = WeakSupportMask & (WeakPixelMask | rowBandMask);
            StrongSeedMask = StrongSeedMask & WeakSupportMask;
            
            if any(StrongSeedMask(:)) && any(WeakSupportMask(:))
                HysteresisMask = imreconstruct(StrongSeedMask,WeakSupportMask);
            else
                HysteresisMask = false(ny,nx);
            end
            
            HysteresisMask = app.keepHorizontalRuns(HysteresisMask,params.MinWidth);
            
            if params.FinalHorizontalBridge > 1
                HysteresisMask = imclose(HysteresisMask,strel('line',params.FinalHorizontalBridge,0));
            end
            
            HysteresisMask = app.keepHorizontalRuns(HysteresisMask,max(3,round(params.MinWidth/2)));
            HysteresisMask = bwareaopen(HysteresisMask,params.MinArea);
            
            FinalMask = app.filterFinalHorizontalStripeComponents_v2( ...
                HysteresisMask, ...
                StrongSeedMask, ...
                StripeScoreMap, ...
                SpectralZMap, ...
                BandConsistencyMap, ...
                params);
            
            if ~isempty(app.CurrentROIMask) && isequal(size(app.CurrentROIMask),size(FinalMask))
                roiMask = logical(app.CurrentROIMask);
            else
                roiMask = true(size(FinalMask));
            end
            
            deletedMask = ...
                false(ny, nx);
            
            if ~isempty(app.DeletedSpectrumMask2D) && ...
                    isequal(size(app.DeletedSpectrumMask2D), [ny nx])
                
                deletedMask = ...
                    logical(app.DeletedSpectrumMask2D);
            end
            
            ArtifactMaskInROI = ...
                FinalMask & roiMask;
            
            ValidPixelMask = ...
                roiMask & ...
                ~FinalMask & ...
                ~deletedMask;
            
            ArtifactTable = app.buildArtifactComponentTableApp(FinalMask,StripeScoreMap,SpectralZMap,BandConsistencyMap);
            
            [CubeDisplayInpaint,DisplayInpaintInfo] = app.inpaintArtifactForDisplaySpectralGuided( ...
                cube,FinalMask,wn,params);
            
            [CleanDisplayMapSpectral,~] = app.buildArtifactDisplayMap( ...
                CubeDisplayInpaint,params.DisplayBandLow,params.DisplayBandHigh);
            
            result = struct();
            result.DisplayMap = displayMap;
            result.DisplayLabel = displayLabel;
            result.CleanDisplayMap = CleanDisplayMapSpectral;
            result.CubeDisplayInpaint = CubeDisplayInpaint;
            result.DisplayInpaintInfo = DisplayInpaintInfo;
            
            result.FinalMask = FinalMask;
            result.ValidPixelMask = ValidPixelMask;
            result.StripeScoreMap = StripeScoreMap;
            result.BandConsistencyMap = BandConsistencyMap;
            result.SpectralZMap = SpectralZMap;
            result.StrongSeedMask = StrongSeedMask;
            result.WeakSupportMask = WeakSupportMask;
            result.HysteresisMask = HysteresisMask;
            result.RowBandMask = rowBandMask;
            result.RowEvidence = rowEvidence;
            result.ArtifactTable = ArtifactTable;
            result.BandMaps = BandMaps;
            result.BandLabels = BandLabels;
            result.ZStack = ZStack;
            result.ArtifactMaskInROI = ArtifactMaskInROI;
            result.ROIMask = roiMask;
            
        end
        
        
        function [BandMaps,BandLabels] = buildMultipleArtifactBandMaps(app,cube,wn,bandDefs,bandNames)
            
            [ny,nx,~] = size(cube);
            BandMaps = zeros(ny,nx,0);
            BandLabels = {};
            
            for i = 1:size(bandDefs,1)
                low = bandDefs(i,1);
                high = bandDefs(i,2);
                
                useBand = wn >= low & wn <= high;
                
                if nnz(useBand) < 3
                    continue
                end
                
                mp = mean(cube(:,:,useBand),3,'omitnan');
                mp = app.cleanArtifactMap(mp);
                
                BandMaps(:,:,end+1) = mp;
                
                if nargin >= 5 && numel(bandNames) >= i
                    BandLabels{end+1} = bandNames{i};
                else
                    BandLabels{end+1} = sprintf('%.0f-%.0f',low,high);
                end
            end
            
            if isempty(BandMaps)
                mp = mean(cube,3,'omitnan');
                BandMaps(:,:,1) = app.cleanArtifactMap(mp);
                BandLabels{1} = 'Total mean projection';
            end
        end
        
        
        function zMap = computeHorizontalStripeResidualZ(app,mapImg,medianFilterHeight)
            
            img = app.cleanArtifactMap(mapImg);
            bgVert = medfilt2(img,[medianFilterHeight 1],'symmetric');
            residual = abs(img - bgVert);
            sig = app.robustSigmaArtifact(residual(:));
            
            zMap = residual ./ sig;
            zMap(~isfinite(zMap)) = 0;
        end
        
        
        function runMask = keepHorizontalRuns(app,pixelMask,minWidth)
            
            [ny,nx] = size(pixelMask);
            runMask = false(ny,nx);
            
            for y = 1:ny
                idx = find(pixelMask(y,:));
                
                if isempty(idx)
                    continue
                end
                
                breaks = [1 find(diff(idx)>1)+1 numel(idx)+1];
                
                for b = 1:numel(breaks)-1
                    runIdx = idx(breaks(b):breaks(b+1)-1);
                    
                    if numel(runIdx) >= minWidth
                        runMask(y,runIdx) = true;
                    end
                end
            end
        end
        
        
        function [rowBandMask,rowEvidence] = computeArtifactRowBandMask(app,StripeScoreMap,WeakSupportMask,params)
            
            [ny,nx] = size(StripeScoreMap);
            
            weakPixels = StripeScoreMap >= params.WeakZ | WeakSupportMask;
            
            rowFraction = mean(weakPixels,2);
            rowMax = max(StripeScoreMap,[],2);
            rowMean = mean(StripeScoreMap,2,'omitnan');
            
            rowFractionZ = app.robustZVectorArtifact(rowFraction);
            rowMaxZ = app.robustZVectorArtifact(rowMax);
            rowMeanZ = app.robustZVectorArtifact(rowMean);
            
            badRows = ...
                (rowFraction >= params.MinRowFraction & rowMeanZ >= params.RowZ) | ...
                (rowFraction >= 0.5*params.MinRowFraction & rowMaxZ >= params.RowZ + 1);
            
            rowBridgeHeight = max(1,2*params.VerticalGrowRadius+1);
            
            if rowBridgeHeight > 1
                badRows = imclose(logical(badRows),ones(rowBridgeHeight,1));
            end
            
            rowBandMask = repmat(logical(badRows),1,nx);
            
            rowEvidence = struct();
            rowEvidence.RowFraction = repmat(rowFraction,1,nx);
            rowEvidence.RowMaxZ = repmat(rowMaxZ,1,nx);
            rowEvidence.RowMeanZ = repmat(rowMeanZ,1,nx);
            rowEvidence.RowFractionZ = repmat(rowFractionZ,1,nx);
            rowEvidence.RowZImage = max(cat(3, ...
                rowEvidence.RowFractionZ, ...
                rowEvidence.RowMaxZ, ...
                rowEvidence.RowMeanZ),[],3);
            rowEvidence.BadRows = badRows;
        end
        
        
        function SpectralZMap = computeSpectralNeighborZMapArtifact(app,cube)
            
            [ny,nx,nWn] = size(cube);
            
            X = reshape(single(cube),ny*nx,nWn);
            
            medX = median(X,2,'omitnan');
            devX = abs(X - medX);
            scaleX = 1.4826 * median(devX,2,'omitnan');
            
            badScale = scaleX <= eps | isnan(scaleX);
            
            if any(badScale)
                scaleX(badScale) = std(X(badScale,:),0,2,'omitnan');
            end
            
            scaleX(scaleX <= eps | isnan(scaleX)) = 1;
            
            Xn = (X - medX) ./ scaleX;
            Xn3 = reshape(Xn,ny,nx,nWn);
            
            distMap = zeros(ny,nx,'single');
            
            if ny == 1
                SpectralZMap = zeros(ny,nx);
                return
            end
            
            for y = 1:ny
                
                if y == 1
                    ref = squeeze(Xn3(2,:,:));
                elseif y == ny
                    ref = squeeze(Xn3(ny-1,:,:));
                else
                    ref = 0.5 * (squeeze(Xn3(y-1,:,:)) + squeeze(Xn3(y+1,:,:)));
                end
                
                cur = squeeze(Xn3(y,:,:));
                d = sqrt(mean((cur - ref).^2,2,'omitnan'));
                distMap(y,:) = d;
            end
            
            vals = double(distMap(isfinite(distMap)));
            
            medv = median(vals,'omitnan');
            sig = app.robustSigmaArtifact(vals);
            
            SpectralZMap = (double(distMap) - medv) ./ sig;
            SpectralZMap(SpectralZMap < 0) = 0;
            SpectralZMap(~isfinite(SpectralZMap)) = 0;
        end
        
        
        function finalMask = filterFinalHorizontalStripeComponents_v2(app,inputMask,seedMask,StripeScoreMap,SpectralZMap,BandConsistencyMap,params)
            
            cc = bwconncomp(inputMask,8);
            stats = regionprops(cc,'BoundingBox','Area','PixelIdxList');
            
            finalMask = false(size(inputMask));
            [ny,nx] = size(inputMask);
            
            for i = 1:numel(stats)
                
                pix = stats(i).PixelIdxList;
                bb = stats(i).BoundingBox;
                
                w = bb(3);
                h = bb(4);
                
                aspect = w / max(h,1);
                area = stats(i).Area;
                
                meanStripeScore = mean(StripeScoreMap(pix),'omitnan');
                maxStripeScore = max(StripeScoreMap(pix),[],'omitnan');
                meanSpectralZ = mean(SpectralZMap(pix),'omitnan');
                meanBandConsistency = mean(BandConsistencyMap(pix),'omitnan');
                
                overlapSeed = any(seedMask(pix));
                touchesBorder = app.componentTouchesBorderArtifact(pix,ny,nx);
                rowSupport = w / nx;
                
                keepThinStripe = ...
                    h <= params.MaxHeight && ...
                    aspect >= params.MinAspect;
                
                keepThickSeededStripe = ...
                    overlapSeed && ...
                    rowSupport >= 0.12 && ...
                    h <= max(params.MaxHeight,70);
                
                keepShape = ...
                    w >= params.MinWidth && ...
                    area >= params.MinArea && ...
                    (keepThinStripe || keepThickSeededStripe);
                
                keepEvidence = ...
                    overlapSeed || ...
                    maxStripeScore >= params.StrongZ || ...
                    (meanStripeScore >= params.WeakZ && meanBandConsistency >= max(1,params.MinBandConsistency-1));
                
                keepEvidence = keepEvidence || ...
                    (meanStripeScore >= params.WeakZ && meanSpectralZ >= params.SpectralZ);
                
                keepBorder = true;
                
                if params.RemoveBorderTouching && touchesBorder
                    keepBorder = false;
                end
                
                if keepShape && keepEvidence && keepBorder
                    finalMask(pix) = true;
                end
            end
            
            finalMask = bwareaopen(finalMask,params.MinArea);
        end
        
        
        function tf = componentTouchesBorderArtifact(app,pix,ny,nx)
            
            [y,x] = ind2sub([ny nx],pix);
            tf = any(y == 1 | y == ny | x == 1 | x == nx);
        end
        
        
        function T = buildArtifactComponentTableApp(app,FinalMask,StripeScoreMap,SpectralZMap,BandConsistencyMap)
            
            cc = bwconncomp(FinalMask,8);
            stats = regionprops(cc,'BoundingBox','Area','PixelIdxList','Centroid');
            
            n = numel(stats);
            
            ID = (1:n)';
            YCenter = zeros(n,1);
            XCenter = zeros(n,1);
            Width = zeros(n,1);
            Height = zeros(n,1);
            AspectRatio = zeros(n,1);
            Area = zeros(n,1);
            MeanStripeScore = zeros(n,1);
            MaxStripeScore = zeros(n,1);
            MeanSpectralZ = zeros(n,1);
            MeanBandConsistency = zeros(n,1);
            
            for i = 1:n
                pix = stats(i).PixelIdxList;
                bb = stats(i).BoundingBox;
                cen = stats(i).Centroid;
                
                XCenter(i) = cen(1);
                YCenter(i) = cen(2);
                
                Width(i) = bb(3);
                Height(i) = bb(4);
                AspectRatio(i) = Width(i) / max(Height(i),1);
                Area(i) = stats(i).Area;
                
                MeanStripeScore(i) = mean(StripeScoreMap(pix),'omitnan');
                MaxStripeScore(i) = max(StripeScoreMap(pix),[],'omitnan');
                MeanSpectralZ(i) = mean(SpectralZMap(pix),'omitnan');
                MeanBandConsistency(i) = mean(BandConsistencyMap(pix),'omitnan');
            end
            
            T = table( ...
                ID, ...
                YCenter, ...
                XCenter, ...
                Width, ...
                Height, ...
                AspectRatio, ...
                Area, ...
                MeanStripeScore, ...
                MaxStripeScore, ...
                MeanSpectralZ, ...
                MeanBandConsistency);
        end
        
        
        %% ============================================================
        %  DISPLAY-ONLY SPECTRAL INPAINTING
        %% ============================================================
        
        function [CubeFilled,Info] = inpaintArtifactForDisplaySpectralGuided(app,Cube,artifactMask,wn,params)
            
            opts = struct();
            opts.RadiusY = params.InpaintRadiusY;
            opts.RadiusX = params.InpaintRadiusX;
            opts.MinNeighbors = params.InpaintMinNeighbors;
            opts.MaxNeighbors = params.InpaintMaxNeighbors;
            opts.SpatialSigmaY = params.InpaintSpatialSigmaY;
            opts.SpatialSigmaX = params.InpaintSpatialSigmaX;
            opts.SpectralSigma = params.InpaintSpectralSigma;
            opts.IntensitySigma = params.InpaintIntensitySigma;
            opts.ReferenceBandLow = params.InpaintReferenceBandLow;
            opts.ReferenceBandHigh = params.InpaintReferenceBandHigh;
            opts.UseWeightedMedian = params.InpaintUseWeightedMedian;
            opts.MinConfidence = params.InpaintMinConfidence;
            
            Cube = double(Cube);
            artifactMask = logical(artifactMask);
            wn = double(wn(:));
            
            [ny,nx,nWn] = size(Cube);
            
            CubeFilled = Cube;
            
            ConfidenceMap = zeros(ny,nx);
            FilledMask = false(ny,nx);
            FailedMask = false(ny,nx);
            
            validMask = ~artifactMask;
            
            useRef = wn >= opts.ReferenceBandLow & wn <= opts.ReferenceBandHigh;
            
            if nnz(useRef) < 10
                useRef = true(nWn,1);
            end
            
            X = reshape(Cube,ny*nx,nWn);
            
            [artifactY,artifactX] = find(artifactMask);
            
            for p = 1:numel(artifactY)
                
                y = artifactY(p);
                x = artifactX(p);
                
                y1 = max(1,y-opts.RadiusY);
                y2 = min(ny,y+opts.RadiusY);
                x1 = max(1,x-opts.RadiusX);
                x2 = min(nx,x+opts.RadiusX);
                
                localValid = validMask(y1:y2,x1:x2);
                
                if nnz(localValid) < opts.MinNeighbors
                    FailedMask(y,x) = true;
                    continue
                end
                
                [yy,xx] = find(localValid);
                yy = yy + y1 - 1;
                xx = xx + x1 - 1;
                
                linIdx = sub2ind([ny nx],yy,xx);
                neighborSpec = X(linIdx,:);
                
                good = all(isfinite(neighborSpec),2);
                
                yy = yy(good);
                xx = xx(good);
                neighborSpec = neighborSpec(good,:);
                
                if size(neighborSpec,1) < opts.MinNeighbors
                    FailedMask(y,x) = true;
                    continue
                end
                
                dy = double(yy) - double(y);
                dx = double(xx) - double(x);
                
                anisotropicD2 = ...
                    (dy.^2 ./ max(opts.SpatialSigmaY^2,eps)) + ...
                    (dx.^2 ./ max(opts.SpatialSigmaX^2,eps));
                
                [~,ord] = sort(anisotropicD2,'ascend');
                ord = ord(1:min(numel(ord),opts.MaxNeighbors));
                
                anisotropicD2 = anisotropicD2(ord);
                neighborSpec = neighborSpec(ord,:);
                
                contextSpec = median(neighborSpec,1,'omitnan');
                
                refNeighbor = neighborSpec(:,useRef);
                refContext = contextSpec(useRef);
                
                refNeighborNorm = app.normalizeRowsRobustArtifact(refNeighbor);
                refContextNorm = app.normalizeRowsRobustArtifact(refContext);
                
                spectralDist = app.spectralAngleDistanceArtifact(refNeighborNorm,refContextNorm);
                
                neighborIntensity = mean(refNeighbor,2,'omitnan');
                contextIntensity = mean(refContext,'omitnan');
                
                intensityScale = app.robustSigmaArtifact(neighborIntensity);
                intensityDist = abs(neighborIntensity - contextIntensity) ./ max(intensityScale,eps);
                
                spatialWeight = exp(-0.5 * anisotropicD2(:));
                spectralWeight = exp(-(spectralDist(:).^2) ./ (2*opts.SpectralSigma^2));
                intensityWeight = exp(-(intensityDist(:).^2) ./ (2*opts.IntensitySigma^2));
                
                w = spatialWeight .* spectralWeight .* intensityWeight;
                w(~isfinite(w)) = 0;
                
                if sum(w) <= eps
                    FailedMask(y,x) = true;
                    continue
                end
                
                w = w ./ sum(w);
                
                ess = 1 / max(sum(w.^2),eps);
                confidence = ess / numel(w);
                
                ConfidenceMap(y,x) = confidence;
                
                if confidence < opts.MinConfidence
                    FailedMask(y,x) = true;
                    continue
                end
                
                if opts.UseWeightedMedian
                    filledSpec = app.weightedMedianSpectrumArtifact(neighborSpec,w);
                else
                    filledSpec = sum(neighborSpec .* w,1);
                end
                
                CubeFilled(y,x,:) = reshape(filledSpec,1,1,[]);
                FilledMask(y,x) = true;
            end
            
            if any(FailedMask(:))
                CubeFilled = app.fallbackFillFailedArtifactPixels(CubeFilled,Cube,artifactMask,FailedMask,opts);
            end
            
            Info = struct();
            Info.ConfidenceMap = ConfidenceMap;
            Info.FilledMask = FilledMask;
            Info.FailedMask = FailedMask;
            Info.Options = opts;
            Info.Method = 'spectral-guided local spatial inpainting for display only';
        end
        
        
        function CubeFilled = fallbackFillFailedArtifactPixels(app,CubeFilled,CubeOriginal,artifactMask,failedMask,opts)
            
            [ny,nx,nWn] = size(CubeOriginal);
            
            Xfilled = reshape(CubeFilled,ny*nx,nWn);
            Xorig = reshape(CubeOriginal,ny*nx,nWn);
            
            validMask = ~artifactMask & ~failedMask;
            
            [ys,xs] = find(failedMask);
            
            for i = 1:numel(ys)
                
                y = ys(i);
                x = xs(i);
                
                y1 = max(1,y-opts.RadiusY);
                y2 = min(ny,y+opts.RadiusY);
                x1 = max(1,x-opts.RadiusX);
                x2 = min(nx,x+opts.RadiusX);
                
                localValid = validMask(y1:y2,x1:x2);
                
                if nnz(localValid) < 3
                    continue
                end
                
                [yy,xx] = find(localValid);
                yy = yy + y1 - 1;
                xx = xx + x1 - 1;
                
                linIdx = sub2ind([ny nx],yy,xx);
                specBlock = Xorig(linIdx,:);
                
                good = all(isfinite(specBlock),2);
                specBlock = specBlock(good,:);
                
                if isempty(specBlock)
                    continue
                end
                
                fillSpec = median(specBlock,1,'omitnan');
                
                targetIdx = sub2ind([ny nx],y,x);
                Xfilled(targetIdx,:) = fillSpec;
            end
            
            CubeFilled = reshape(Xfilled,ny,nx,nWn);
        end
        
        
        function Xn = normalizeRowsRobustArtifact(app,X)
            
            X = double(X);
            
            if isvector(X)
                X = X(:).';
            end
            
            medX = median(X,2,'omitnan');
            Xc = X - medX;
            
            scale = 1.4826 * median(abs(Xc),2,'omitnan');
            bad = scale <= eps | isnan(scale);
            
            if any(bad)
                scale(bad) = std(X(bad,:),0,2,'omitnan');
            end
            
            scale(scale <= eps | isnan(scale)) = 1;
            
            Xn = Xc ./ scale;
            Xn(~isfinite(Xn)) = 0;
        end
        
        
        function d = spectralAngleDistanceArtifact(app,A,b)
            
            A = double(A);
            b = double(b);
            
            if size(b,1) ~= 1
                b = b(:).';
            end
            
            nb = sqrt(sum(b.^2));
            
            if nb <= eps
                d = ones(size(A,1),1) * pi/2;
                return
            end
            
            na = sqrt(sum(A.^2,2));
            na(na <= eps) = eps;
            
            cosang = (A*b(:)) ./ (na*nb);
            cosang = max(-1,min(1,cosang));
            
            d = acos(cosang);
        end
        
        
        function spec = weightedMedianSpectrumArtifact(app,X,w)
            
            X = double(X);
            w = double(w(:));
            w = w ./ max(sum(w),eps);
            
            [~,m] = size(X);
            spec = zeros(1,m);
            
            for k = 1:m
                
                vals = X(:,k);
                good = isfinite(vals) & isfinite(w) & w > 0;
                
                if ~any(good)
                    spec(k) = NaN;
                    continue
                end
                
                vals = vals(good);
                wg = w(good);
                wg = wg ./ sum(wg);
                
                [valsSorted,ord] = sort(vals);
                cumw = cumsum(wg(ord));
                
                idx = find(cumw >= 0.5,1,'first');
                
                spec(k) = valsSorted(idx);
            end
        end
        
        
        function sig = robustSigmaArtifact(app,v)
            
            v = double(v(:));
            v = v(isfinite(v));
            
            if isempty(v)
                sig = 1;
                return
            end
            
            sig = 1.4826 * mad(v,1);
            
            if sig <= eps || isnan(sig)
                sig = std(v,'omitnan');
            end
            
            if sig <= eps || isnan(sig)
                sig = 1;
            end
        end
        
        
        function z = robustZVectorArtifact(app,v)
            
            v = double(v(:));
            
            medv = median(v,'omitnan');
            sig = app.robustSigmaArtifact(v);
            
            z = (v - medv) ./ sig;
            z(~isfinite(z)) = 0;
            z(z < 0) = 0;
        end
        
        
        %% ============================================================
        %  ARTIFACT PLOTTING / SAVE / RETURN
        %% ============================================================
        
        function plotArtifactResultOnTab(app)
            
            if isempty(app.ArtifactDisplayMapRaw)
                return
            end
            
            app.ArtifactViewMode = app.ViewDropDown.Value;
            app.ArtifactColorMap = app.ColorsDropDown.Value;
            
            % Axis 1: raw display map + red artifact overlay.
            app.resetImageAxis(app.UIAxes3);
            app.showArtifactMapOnAxes(app.UIAxes3,app.ArtifactDisplayMapRaw);
            
            hold(app.UIAxes3,'on');
            
            redOverlay = cat(3, ...
                ones(size(app.ArtifactMask2D)), ...
                zeros(size(app.ArtifactMask2D)), ...
                zeros(size(app.ArtifactMask2D)));
            
            h = imagesc(app.UIAxes3,redOverlay);
            set(h,'AlphaData',0.38*double(app.ArtifactMask2D));
            
            if ~isempty(app.CurrentROIMask) && isequal(size(app.CurrentROIMask),size(app.ArtifactMask2D)) && app.ROIReady
                contour(app.UIAxes3,app.CurrentROIMask,[0.5 0.5],'y','LineWidth',1.0);
            end
            
            hold(app.UIAxes3,'off');
            
            title(app.UIAxes3, ...
                {app.CurrentScanName, ...
                'Raw map + artifact mask', ...
                sprintf('Artifact pixels = %d',nnz(app.ArtifactMask2D))}, ...
                'Interpreter','none');
            
            % Axis 2: selected display mode.
            if strcmp(app.ArtifactViewMode,'Masked artifact as black')
                
                app.plotArtifactMaskedBlackAxis( ...
                    app.UIAxes4, ...
                    app.ArtifactDisplayMapRaw, ...
                    app.ArtifactMask2D, ...
                    {'Raw map with detected artifact pixels in black', ...
                    'No interpolation shown on this view'});
                
            else
                
                app.resetImageAxis(app.UIAxes4);
                app.showArtifactMapOnAxes(app.UIAxes4,app.ArtifactDisplayMapClean);
                
                title(app.UIAxes4, ...
                    {'Interpolated clean display map', ...
                    'visualization only - not used for analysis'}, ...
                    'Interpreter','none');
            end
            
            app.setArtifactText({ ...
                'ARTIFACT DISPLAY UPDATED'; ...
                '================================'; ...
                ''; ...
                ['Scan: ' app.CurrentScanName]; ...
                ['View: ' app.ArtifactViewMode]; ...
                ['Colormap: ' app.ArtifactColorMap]; ...
                ''; ...
                sprintf('Artifact pixels: %d',nnz(app.ArtifactMask2D)); ...
                sprintf('Valid pixels: %d',nnz(app.ValidPixelMask2D)); ...
                sprintf('Artifact components: %d',height(app.ArtifactTable)); ...
                ''; ...
                'Axis 1:'; ...
                '  Raw display map + red artifact overlay.'; ...
                ''; ...
                'Axis 2:'; ...
                '  Interpolated clean map OR raw map with artifact pixels in black.'; ...
                ''; ...
                'Important:'; ...
                '  Interpolated clean map is visualization only.'; ...
                '  Downstream extraction must use ValidPixelMask2D.'});
        end
        
        
        function savedMatPath = saveFinalArtifactData(app)
            
            app.initializeProjectPaths();
            
            if isempty(app.Paths.ArtifactDataRoot)
                
                error( ...
                    'Artifact workspace is unavailable.');
            end
            
            if ~isfolder(app.Paths.ArtifactDataRoot)
                mkdir(app.Paths.ArtifactDataRoot);
            end
            
            % Validate that the active cube still exists in RAM.
            committedCube = ...
                app.validateCosmicToArtifactTransfer();
            
            [ny, nx, nWn] = ...
                size(committedCube);
            
            if numel(app.Wavenumber) ~= nWn
                
                error( ...
                    'Artifact wavenumber does not match active cube.');
            end
            
            % =============================================================
            % MATERIALIZED SPECTRAL DATA
            %
            % Artifact does NOT create another cube.
            % It references the current Cosmic checkpoint.
            % =============================================================
            
            spectralDataPath = ...
                app.getCurrentSpectralDataPath();
            
            originalSourcePath = ...
                app.getOriginalSourceMatPath();
            
            scanID = ...
                app.getCurrentScanID();
            
            savedMatPath = ...
                fullfile( ...
                app.Paths.ArtifactDataRoot, ...
                [scanID '_artifact_result.mat']);
            
            % =============================================================
            % LINKED RAMAN DATA
            % =============================================================
            
            RamanDataArtifact = struct();
            
            RamanDataArtifact.SchemaVersion = ...
                2;
            
            RamanDataArtifact.FormatVersion = ...
                'RamanCube Artifact Checkpoint v2';
            
            RamanDataArtifact.StorageMode = ...
                'linked_spectral_cube';
            
            RamanDataArtifact.Stage = ...
                'Artifact';
            
            RamanDataArtifact.ScanName = ...
                app.CurrentScanName;
            
            RamanDataArtifact.ScanID = ...
                scanID;
            
            RamanDataArtifact.Wavenumber = ...
                double(app.Wavenumber(:));
            
            RamanDataArtifact.CubeSize = ...
                [ny nx nWn];
            
            RamanDataArtifact.SpectralDataPath = ...
                spectralDataPath;
            
            RamanDataArtifact.SpectralDataRelativePath = ...
                app.makeProjectRelativePath( ...
                spectralDataPath);
            
            RamanDataArtifact.OriginalSourceMatPath = ...
                originalSourcePath;
            
            RamanDataArtifact.OriginalSourceRelativePath = ...
                app.makeProjectRelativePath( ...
                originalSourcePath);
            
            % =============================================================
            % ARTIFACT RESULT
            % =============================================================
            
            ArtifactMask2D = ...
                logical(app.ArtifactMask2D);
            
            ValidPixelMask2D = ...
                logical(app.ValidPixelMask2D);
            
            CleanDisplayMap = ...
                app.ArtifactDisplayMapClean;
            
            RawDisplayMap = ...
                app.ArtifactDisplayMapRaw;
            
            ArtifactParams = ...
                app.ArtifactParams;
            
            ArtifactTable = ...
                app.ArtifactTable;
            
            StripeScoreMap = ...
                app.ArtifactStripeScoreMap;
            
            BandConsistencyMap = ...
                app.ArtifactBandConsistencyMap;
            
            SpectralZMap = ...
                app.ArtifactSpectralZMap;
            
            StrongSeedMask = ...
                app.ArtifactStrongSeedMask;
            
            WeakSupportMask = ...
                app.ArtifactWeakSupportMask;
            
            HysteresisMask = ...
                app.ArtifactHysteresisMask;
            
            RowBandMask = ...
                app.ArtifactRowBandMask;
            
            RowEvidence = ...
                app.ArtifactRowEvidence;
            
            DisplayInpaintInfo = ...
                app.ArtifactDisplayInpaintInfo;
            
            ROIMask = ...
                app.CurrentROIMask;
            
            DeletedSpectrumMask2D = ...
                app.DeletedSpectrumMask2D;
            
            DeletedSpectrumTable = ...
                app.DeletedSpectrumTable;
            
            % =============================================================
            % PROCESSING INFO
            % =============================================================
            
            SessionMetadata = ...
                app.buildSessionMetadata();
            
            ProcessingInfo = struct();
            
            ProcessingInfo.SchemaVersion = ...
                2;
            
            ProcessingInfo.ScanName = ...
                app.CurrentScanName;
            
            ProcessingInfo.ScanID = ...
                scanID;
            
            ProcessingInfo.OriginalMatPath = ...
                originalSourcePath;
            
            ProcessingInfo.SpectralDataPath = ...
                spectralDataPath;
            
            ProcessingInfo.ProcessedDate = ...
                datestr(now, 'yyyy-mm-dd HH:MM:SS');
            
            ProcessingInfo.SoftwareStep = ...
                'scanpretreat artifact removal';
            
            ProcessingInfo.StorageMode = ...
                'linked_spectral_cube';
            
            ProcessingInfo.OutputFolder = ...
                app.Paths.ArtifactDataRoot;
            
            ProcessingInfo.OutputMatPath = ...
                savedMatPath;
            
            ProcessingInfo.Note = ...
                ['No Raman cube is duplicated at Artifact stage. ' ...
                'Analytical spectra remain in SpectralDataPath. ' ...
                'Artifact pixels are excluded through masks.'];
            
            ProcessingInfo.User = ...
                SessionMetadata.User;
            
            ProcessingInfo.Email = ...
                SessionMetadata.Email;
            
            ProcessingInfo.Role = ...
                SessionMetadata.Role;
            
            ProcessingInfo.SessionID = ...
                SessionMetadata.SessionID;
            
            % =============================================================
            % PRETREAT STATE
            % =============================================================
            
            PretreatState = struct();
            
            PretreatState.Stage = ...
                'Artifact';
            
            PretreatState.ScanName = ...
                app.CurrentScanName;
            
            PretreatState.ScanID = ...
                scanID;
            
            PretreatState.OriginalMatPath = ...
                originalSourcePath;
            
            PretreatState.SpectralDataPath = ...
                spectralDataPath;
            
            PretreatState.SavedMatPath = ...
                savedMatPath;
            
            PretreatState.ArtifactReady = ...
                app.ArtifactReady;
            
            PretreatState.ArtifactConfirmed = ...
                app.ArtifactConfirmed;
            
            PretreatState.ArtifactPixels = ...
                nnz(ArtifactMask2D);
            
            PretreatState.ValidPixels = ...
                nnz(ValidPixelMask2D);
            
            PretreatState.DeletedSpectra = ...
                nnz(DeletedSpectrumMask2D);
            
            PretreatState.ProcessedDate = ...
                datestr(now, 'yyyy-mm-dd HH:MM:SS');
            
            PretreatState.StorageMode = ...
                'linked_spectral_cube';
            
            % =============================================================
            % SAVE
            %
            % IMPORTANT:
            % CosmicMask / AuxCosmicMask are intentionally not copied again.
            % They already belong to the Cosmic checkpoint.
            % =============================================================
            
            tempPath = ...
                [savedMatPath '.tmp.mat'];
            
            save( ...
                tempPath, ...
                'RamanDataArtifact', ...
                'ArtifactMask2D', ...
                'ValidPixelMask2D', ...
                'CleanDisplayMap', ...
                'RawDisplayMap', ...
                'ArtifactParams', ...
                'ArtifactTable', ...
                'StripeScoreMap', ...
                'BandConsistencyMap', ...
                'SpectralZMap', ...
                'StrongSeedMask', ...
                'WeakSupportMask', ...
                'HysteresisMask', ...
                'RowBandMask', ...
                'RowEvidence', ...
                'DisplayInpaintInfo', ...
                'ROIMask', ...
                'ProcessingInfo', ...
                'PretreatState', ...
                'DeletedSpectrumMask2D', ...
                'DeletedSpectrumTable', ...
                '-v7.3');
            
            if isfile(savedMatPath)
                delete(savedMatPath);
            end
            
            [ok, messageText] = ...
                movefile( ...
                tempPath, ...
                savedMatPath, ...
                'f');
            
            if ~ok
                
                error( ...
                    'Could not commit Artifact checkpoint:%s%s', ...
                    newline, ...
                    messageText);
            end
            
        end
        
        
        function plotCosmicBeforeAfterMapReview(app)
            
            if isempty(app.CubeOriginal)
                return
            end
            
            if isempty(app.CorrectedCube)
                uialert(app.UIFigure, ...
                    'No corrected cube is available. Please press Apply for all first.', ...
                    'No corrected cube');
                return
            end
            
            % Build 2840-2940 cm^-1 maps.
            [rawMap, rawLabel] = app.buildRamanMap(app.CubeOriginal);
            [cosmicMap, cosmicLabel] = app.buildRamanMap(app.CorrectedCube);
            
            % Axis 1: raw map before cosmic correction.
            ax1 = app.UIAxes;
            app.resetImageAxis(ax1);
            app.showMapOnNormalAxes(ax1, rawMap);
            title(ax1, { ...
                app.CurrentScanName, ...
                ['RAW map before cosmic correction | ' rawLabel]}, ...
                'Interpreter','none');
            
            % Axis 2: map after cosmic correction.
            ax2 = app.UIAxes2;
            app.resetImageAxis(ax2);
            app.showMapOnNormalAxes(ax2, cosmicMap);
            title(ax2, { ...
                app.CurrentScanName, ...
                ['AFTER cosmic correction | ' cosmicLabel]}, ...
                'Interpreter','none');
            
            % Optional ROI contour on both maps.
            if ~isempty(app.CurrentROIMask) && any(app.CurrentROIMask(:)) && app.ROIReady
                hold(ax1,'on');
                contour(ax1, app.CurrentROIMask, [0.5 0.5], 'y', 'LineWidth', 1.1);
                hold(ax1,'off');
                
                hold(ax2,'on');
                contour(ax2, app.CurrentROIMask, [0.5 0.5], 'y', 'LineWidth', 1.1);
                hold(ax2,'off');
            end
            
            app.fixAxisMargin(ax1,false);
            app.fixAxisMargin(ax2,false);
            
            nAccepted = 0;
            nCorrected = 0;
            
            if ~isempty(app.CandidateTable)
                nAccepted = nnz(app.CandidateTable.Accepted);
                if ismember('Corrected', app.CandidateTable.Properties.VariableNames)
                    nCorrected = nnz(app.CandidateTable.Corrected);
                end
            end
            
            app.setText({ ...
                'COSMIC CORRECTION REVIEW'; ...
                ''; ...
                ['Scan: ' app.CurrentScanName]; ...
                'Display band: 2840-2940 cm^-1'; ...
                ''; ...
                sprintf('Accepted cosmic candidates: %d', nAccepted); ...
                sprintf('Corrected cosmic candidates: %d', nCorrected); ...
                ''; ...
                'Axis 1: raw map before cosmic correction.'; ...
                'Axis 2: map after cosmic correction.'; ...
                ''; ...
                'Note:'; ...
                'This review is for visual QC of the effect of cosmic correction on the Raman map.'});
        end
        
        
        %% ============================================================
        %  COSMIC DETECTION MODES
        %% ============================================================
        

        function params = getCosmicDetectionPresetParams(app, modeValue)
            
            % =============================================================
            % COSMIC DETECTION PRESETS
            %
            % Heavy:
            %   Sensitive detection.
            %   Lower thresholds, wider support, more candidates.
            %
            % Medium:
            %   Balanced default.
            %
            % Light:
            %   Conservative detection.
            %   Higher thresholds, narrower support, fewer false positives.
            %
            % Manual:
            %   Preserve current manually edited parameters.
            % =============================================================
            
            modeValue = ...
                char(string(modeValue));
            
            % Normalize spelling/case.
            validModes = { ...
                'Heavy', ...
                'Medium', ...
                'Light', ...
                'Manual'};
            
            matchedIndex = ...
                find(strcmpi(modeValue, validModes), 1);
            
            if isempty(matchedIndex)
                modeValue = 'Medium';
            else
                modeValue = validModes{matchedIndex};
            end
            
            % =============================================================
            % MANUAL MUST PRESERVE CURRENT VALUES
            % =============================================================
            
            if strcmp(modeValue, 'Manual')
                
                if isempty(app.DetectionParams) || ...
                        ~isstruct(app.DetectionParams)
                    
                    params = struct();
                else
                    params = app.DetectionParams;
                end
                
                params.Mode = ...
                    "Manual";
                
                params.Method = ...
                    "Derivative + local residual";
                
                params = ...
                    app.sanitizeDetectionParams(params);
                
                return
            end
            
            % =============================================================
            % COMMON RESOLUTION-AWARE VALUES
            % =============================================================
            
            sampling = ...
                max(app.SpectralSampling, eps);
            
            resolution = ...
                max(app.SpectralResolution, sampling);
            
            pointsPerR = ...
                max(1, ceil(resolution / sampling));
            
            params = struct();
            
            params.Mode = ...
                string(modeValue);
            
            params.Method = ...
                "Derivative + local residual";
            
            params.DerivativeOrder = ...
                2;
            
            % =============================================================
            % PRESETS
            %
            % Core ThresholdK / MinJumpK values are retained from the
            % validated older version.
            % =============================================================
            
            switch modeValue
                
                case 'Heavy'
                    
                    % Sensitive.
                    params.ThresholdK = 6.0;
                    params.MinJumpK = 4.0;
                    
                    params.LocalWindow = 9;
                    
                    % Legacy equivalent was 4 points, but now this must be
                    % resolution-aware.
                    params.MaxSpikeWidth = ...
                        max(4, ceil(3.5 * pointsPerR));
                    
                    % Merge nearby strong seeds into one complex event.
                    params.MergeDistanceR = 3.5;
                    
                    % Events up to this FWHM/R ratio may remain automatic.
                    params.MaxAutomaticWidthR = 3.5;
                    
                    % Lower return threshold expands weak shoulders/tails.
                    params.SupportReturnK = 2.0;
                    
                    % Thresholds used to build weak support.
                    params.WeakDerivativeFraction = 0.32;
                    params.WeakResidualFraction = 0.28;
                    
                case 'Light'
                    
                    % Conservative.
                    params.ThresholdK = 10.0;
                    params.MinJumpK = 8.0;
                    
                    params.LocalWindow = 7;
                    
                    params.MaxSpikeWidth = ...
                        max(2, ceil(2.0 * pointsPerR));
                    
                    params.MergeDistanceR = 2.0;
                    params.MaxAutomaticWidthR = 2.5;
                    
                    % Higher support return threshold gives tighter regions.
                    params.SupportReturnK = 3.5;
                    
                    params.WeakDerivativeFraction = 0.50;
                    params.WeakResidualFraction = 0.45;
                    
                otherwise
                    % Medium / balanced.
                    
                    params.Mode = ...
                        "Medium";
                    
                    params.ThresholdK = 8.0;
                    params.MinJumpK = 6.0;
                    
                    params.LocalWindow = 9;
                    
                    params.MaxSpikeWidth = ...
                        max(3, ceil(3.0 * pointsPerR));
                    
                    params.MergeDistanceR = 3.0;
                    params.MaxAutomaticWidthR = 3.0;
                    params.SupportReturnK = 2.5;
                    
                    params.WeakDerivativeFraction = 0.40;
                    params.WeakResidualFraction = 0.35;
            end
            
            params = ...
                app.sanitizeDetectionParams(params);
        end
        
        
        function plotTrialCorrectionResult( ...
                app, ...
                idx, ...
                beforeSpectrum, ...
                afterSpectrum)
            
            T = ...
                app.CandidateTable;
            
            localIdx = ...
                app.getWNWindow( ...
                T.KPeak(idx), ...
                110, ...
                45);
            
            app.resetSpectrumAxis(app.UIAxes);
            
            plot( ...
                app.UIAxes, ...
                app.Wavenumber(localIdx), ...
                beforeSpectrum(localIdx), ...
                'r--', ...
                'LineWidth', ...
                1.3);
            
            hold(app.UIAxes, 'on');
            
            plot( ...
                app.UIAxes, ...
                app.Wavenumber(localIdx), ...
                afterSpectrum(localIdx), ...
                'b-', ...
                'LineWidth', ...
                1.6);
            
            xline( ...
                app.UIAxes, ...
                app.Wavenumber(T.KStart(idx)), ...
                'k:', ...
                'LineWidth', ...
                1.0);
            
            xline( ...
                app.UIAxes, ...
                app.Wavenumber(T.KEnd(idx)), ...
                'k:', ...
                'LineWidth', ...
                1.0);
            
            hold(app.UIAxes, 'off');
            
            title( ...
                app.UIAxes, ...
                sprintf( ...
                'Trial | Candidate %03d | %s | %s', ...
                idx, ...
                char(T.EventType(idx)), ...
                char(app.CorrectParams.Method)), ...
                'Interpreter', ...
                'none');
            
            legend( ...
                app.UIAxes, ...
                {'Before', 'Trial corrected', ...
                'Correction start', 'Correction end'}, ...
                'Location', ...
                'best');
            
            app.setNiceSpectralLimits( ...
                app.UIAxes, ...
                app.Wavenumber(localIdx), ...
                [beforeSpectrum(localIdx); ...
                afterSpectrum(localIdx)]);
            
            app.resetSpectrumAxis(app.UIAxes2);
            
            plot( ...
                app.UIAxes2, ...
                app.Wavenumber, ...
                beforeSpectrum, ...
                'Color', ...
                [0.6 0.6 0.6], ...
                'LineWidth', ...
                0.9);
            
            hold(app.UIAxes2, 'on');
            
            plot( ...
                app.UIAxes2, ...
                app.Wavenumber, ...
                afterSpectrum, ...
                'b-', ...
                'LineWidth', ...
                1.1);
            
            hold(app.UIAxes2, 'off');
            
            title( ...
                app.UIAxes2, ...
                'Full-spectrum context', ...
                'Interpreter', ...
                'none');
            
            legend( ...
                app.UIAxes2, ...
                {'Before', 'Trial corrected'}, ...
                'Location', ...
                'best');
            
            app.setNiceSpectralLimits( ...
                app.UIAxes2, ...
                app.Wavenumber, ...
                [beforeSpectrum; afterSpectrum]);
        end
        
        
        function txt = getCosmicDetectionModeTooltip(app,modeValue)
            
            modeValue = char(modeValue);
            
            if strcmp(modeValue,'Heavy')
                txt = sprintf([ ...
                    'Heavy detection:\n', ...
                    'Sensitive derivative mode.\n', ...
                    'Use when weak cosmic spikes are missed.\n', ...
                    'Expect more candidates and more manual review.']);
                
            elseif strcmp(modeValue,'Light')
                txt = sprintf([ ...
                    'Light detection:\n', ...
                    'Conservative derivative mode.\n', ...
                    'Use when too many false positives appear.\n', ...
                    'May miss weak cosmic spikes.']);
                
            elseif strcmp(modeValue,'Manual')
                txt = sprintf([ ...
                    'Manual detection:\n', ...
                    'Open full parameter editor.\n', ...
                    'Use when presets are not suitable for this scan.']);
                
            else
                txt = sprintf([ ...
                    'Medium detection:\n', ...
                    'Balanced derivative mode.\n', ...
                    'Recommended first choice for most scans.']);
            end
        end
        
        
        function lines = makeCosmicDetectionModeText( ...
                app, params, modeValue)
            
            params = ...
                app.sanitizeDetectionParams(params);
            
            modeValue = ...
                char(string(modeValue));
            
            lines = { ...
                'COSMIC DETECTION MODE'; ...
                '================================'; ...
                ''; ...
                ['Mode: ' modeValue]; ...
                ['Stored mode: ' char(params.Mode)]; ...
                ['Method: ' char(params.Method)]; ...
                ''; ...
                sprintf('Derivative order: %d', ...
                params.DerivativeOrder); ...
                sprintf('ThresholdK: %.3f', ...
                params.ThresholdK); ...
                sprintf('MinJumpK: %.3f', ...
                params.MinJumpK); ...
                sprintf('Local window: %d points', ...
                params.LocalWindow); ...
                sprintf('Maximum event width: %d points', ...
                params.MaxSpikeWidth); ...
                ''; ...
                sprintf('Merge distance: %.2f R', ...
                params.MergeDistanceR); ...
                sprintf('Maximum automatic width: %.2f R', ...
                params.MaxAutomaticWidthR); ...
                sprintf('Support return threshold: %.3f Z', ...
                params.SupportReturnK); ...
                sprintf('Weak derivative fraction: %.3f', ...
                params.WeakDerivativeFraction); ...
                sprintf('Weak residual fraction: %.3f', ...
                params.WeakResidualFraction); ...
                ''; ...
                sprintf('Spectral sampling: %.4g cm^{-1}/point', ...
                app.SpectralSampling); ...
                sprintf('Spectral resolution: %.4g cm^{-1}', ...
                app.SpectralResolution); ...
                ''; ...
                'These exact parameters will be used when'; ...
                'Cosmic detection is pressed.'};
        end
        
        
        function params = editDetectionParametersManual(app, startParams)
            
            % =============================================================
            % MANUAL COSMIC DETECTION PARAMETER EDITOR
            % =============================================================
            
            if nargin < 2 || ...
                    isempty(startParams) || ...
                    ~isstruct(startParams)
                
                startParams = ...
                    app.getCosmicDetectionPresetParams('Medium');
            end
            
            startParams = ...
                app.sanitizeDetectionParams(startParams);
            
            prompts = { ...
                'Derivative order, 1 or 2'; ...
                'Strong derivative threshold, ThresholdK'; ...
                'Strong positive residual threshold, MinJumpK'; ...
                'Local median window, odd number'; ...
                'Maximum event width, spectral points'; ...
                'Seed merge distance, units of R'; ...
                'Maximum automatic FWHM, units of R'; ...
                'Support return threshold, Z'; ...
                'Weak derivative fraction, 0-1'; ...
                'Weak residual fraction, 0-1'};
            
            defaults = { ...
                num2str(startParams.DerivativeOrder); ...
                num2str(startParams.ThresholdK); ...
                num2str(startParams.MinJumpK); ...
                num2str(startParams.LocalWindow); ...
                num2str(startParams.MaxSpikeWidth); ...
                num2str(startParams.MergeDistanceR); ...
                num2str(startParams.MaxAutomaticWidthR); ...
                num2str(startParams.SupportReturnK); ...
                num2str(startParams.WeakDerivativeFraction); ...
                num2str(startParams.WeakResidualFraction)};
            guideText = { ...
                'MANUAL COSMIC DETECTION GUIDE'; ...
                '------------------------'; ...
                ''; ...
                'Validated presets:'; ...
                ''; ...
                'HEAVY — sensitive'; ...
                '  ThresholdK = 6'; ...
                '  MinJumpK = 4'; ...
                '  Weak fractions = 0.32 / 0.28'; ...
                '  Use when weak cosmic events are missed.'; ...
                ''; ...
                'MEDIUM — balanced'; ...
                '  ThresholdK = 8'; ...
                '  MinJumpK = 6'; ...
                '  Weak fractions = 0.40 / 0.35'; ...
                '  Recommended first choice.'; ...
                ''; ...
                'LIGHT — strict / conservative'; ...
                '  ThresholdK = 10'; ...
                '  MinJumpK = 8'; ...
                '  Weak fractions = 0.50 / 0.45'; ...
                '  Use when too many false candidates appear.'; ...
                ''; ...
                'To make detection stricter:'; ...
                '  Increase ThresholdK and MinJumpK.'; ...
                '  Increase weak fractions.'; ...
                '  Reduce MergeDistanceR.'; ...
                '  Reduce MaxAutomaticWidthR.'; ...
                ''; ...
                'To make detection more sensitive:'; ...
                '  Decrease ThresholdK and MinJumpK.'; ...
                '  Decrease weak fractions.'; ...
                '  Increase MergeDistanceR carefully.'};
            
            uialert( ...
                app.UIFigure, ...
                guideText, ...
                'Manual detection guide', ...
                'Icon', ...
                'info');
            
            
            answer = ...
                inputdlg( ...
                prompts, ...
                'Manual cosmic detection parameters', ...
                [1 75], ...
                defaults);
            
            if isempty(answer)
                
                params = [];
                return
            end
            
            values = ...
                nan(numel(answer), 1);
            
            for k = 1:numel(answer)
                values(k) = ...
                    str2double(answer{k});
            end
            
            if any(~isfinite(values))
                
                uialert( ...
                    app.UIFigure, ...
                    'Every manual detection parameter must be numeric.', ...
                    'Invalid manual parameters');
                
                params = [];
                return
            end
            
            params = ...
                startParams;
            
            params.Mode = ...
                "Manual";
            
            params.Method = ...
                "Derivative + local residual";
            
            params.DerivativeOrder = ...
                values(1);
            
            params.ThresholdK = ...
                values(2);
            
            params.MinJumpK = ...
                values(3);
            
            params.LocalWindow = ...
                values(4);
            
            params.MaxSpikeWidth = ...
                values(5);
            
            params.MergeDistanceR = ...
                values(6);
            
            params.MaxAutomaticWidthR = ...
                values(7);
            
            params.SupportReturnK = ...
                values(8);
            
            params.WeakDerivativeFraction = ...
                values(9);
            
            params.WeakResidualFraction = ...
                values(10);
            
            params = ...
                app.sanitizeDetectionParams(params);
        end
        
        %% ============================================================
        %  COSMIC CORRECTION METHODS AND PARAMETER MODES
        %% ============================================================
        
        function txt = getCorrectionMethodTooltip(app,methodValue)
            
            methodValue = char(methodValue);
            
            if strcmp(methodValue,'Linear interpolation')
                txt = sprintf([ ...
                    'Linear interpolation:\n', ...
                    'Fastest and safest for narrow single-point cosmic spikes.\n', ...
                    'Recommended default for most corrected candidates.']);
                
            elseif strcmp(methodValue,'PCHIP interpolation')
                txt = sprintf([ ...
                    'PCHIP interpolation:\n', ...
                    'Shape-preserving interpolation.\n', ...
                    'Useful when local baseline is curved but should not overshoot strongly.']);
                
            elseif strcmp(methodValue,'Spline interpolation')
                txt = sprintf([ ...
                    'Spline interpolation:\n', ...
                    'Smooth interpolation.\n', ...
                    'Use carefully because it may overshoot near sharp Raman bands.']);
                
            elseif strcmp(methodValue,'Local median')
                txt = sprintf([ ...
                    'Local median:\n', ...
                    'Robust for noisy spectra and unstable local baseline.\n', ...
                    'May flatten narrow real Raman features if too aggressive.']);
                
            else
                txt = 'Cosmic correction method.';
            end
        end
        
        
        function params = getCosmicCorrectionPresetParams( ...
                app, modeName, methodName)
            
            modeName = ...
                char(modeName);
            
            methodName = ...
                char(methodName);
            
            sampling = ...
                max(app.SpectralSampling, eps);
            
            resolution = ...
                max(app.SpectralResolution, sampling);
            
            pointsPerR = ...
                max(1, ceil(resolution / sampling));
            
            params = struct();
            
            params.Method = ...
                string(methodName);
            
            switch lower(modeName)
                
                case 'light'
                    
                    params.ExpandPoints = ...
                        0;
                    
                    params.SupportPoints = ...
                        max(2, round(1.0 * pointsPerR));
                    
                    params.MedianWindow = ...
                        max(5, 2 * floor(pointsPerR / 2) + 3);
                    
                    params.Severity = ...
                        "Light";
                    
                case 'heavy'
                    
                    params.ExpandPoints = ...
                        max(1, round(1.0 * pointsPerR));
                    
                    params.SupportPoints = ...
                        max(4, round(2.0 * pointsPerR));
                    
                    params.MedianWindow = ...
                        max(9, 2 * pointsPerR + 3);
                    
                    params.Severity = ...
                        "Heavy";
                    
                otherwise
                    
                    params.ExpandPoints = ...
                        max(0, round(0.5 * pointsPerR));
                    
                    params.SupportPoints = ...
                        max(3, round(1.5 * pointsPerR));
                    
                    params.MedianWindow = ...
                        max(7, 2 * floor(pointsPerR / 2) + 5);
                    
                    params.Severity = ...
                        "Medium";
            end
            
            if mod(params.MedianWindow, 2) == 0
                params.MedianWindow = ...
                    params.MedianWindow + 1;
            end
        end
        
        
        function params = sanitizeCorrectionParams(app,params)
            
            params.ExpandPoints = max(0,round(params.ExpandPoints));
            params.SupportPoints = max(1,round(params.SupportPoints));
            params.MedianWindow = max(3,round(params.MedianWindow));
            
            if mod(params.MedianWindow,2)==0
                params.MedianWindow = params.MedianWindow + 1;
            end
            
            if ~isfield(params,'Method') || strlength(string(params.Method)) == 0
                params.Method = string(app.MethodsDropDown_2.Value);
            end
        end
        
        
        function lines = makeCorrectionParameterModeText(app,params,modeValue)
            
            methodValue = char(params.Method);
            modeValue = char(modeValue);
            
            lines = { ...
                'COSMIC CORRECTION PARAMETERS'; ...
                '================================'; ...
                ''; ...
                ['Correction method: ' methodValue]; ...
                ['Parameter mode: ' modeValue]; ...
                ''; ...
                'Current parameters:'; ...
                sprintf('  ExpandPoints: %d',params.ExpandPoints); ...
                sprintf('  SupportPoints: %d',params.SupportPoints); ...
                sprintf('  MedianWindow: %d',params.MedianWindow); ...
                ''; ...
                'Parameter meaning:'; ...
                '  ExpandPoints:'; ...
                '    Adds extra points around the detected spike.'; ...
                '    Increase if spike tail remains after correction.'; ...
                '    Too high may remove true Raman peak shoulders.'; ...
                ''; ...
                '  SupportPoints:'; ...
                '    Number of clean points on each side used for interpolation.'; ...
                '    Increase for a more stable local baseline.'; ...
                '    Too high may cross real Raman bands.'; ...
                ''; ...
                '  MedianWindow:'; ...
                '    Used mainly by Local median correction.'; ...
                '    Larger = stronger smoothing.'; ...
                '    Too large may flatten narrow real Raman features.'; ...
                ''; ...
                'Specific cases:'; ...
                '  Narrow single-point spike: Light, Linear interpolation.'; ...
                '  Slight spike tail remains: Medium or Heavy, ExpandPoints = 1.'; ...
                '  Curved local baseline: PCHIP with Medium parameters.'; ...
                '  Noisy spectrum: Local median, MedianWindow 9-11.'; ...
                '  Risk of overshoot near Raman peak: avoid Spline, use Linear or PCHIP.'};
        end
        
        
        function modeValue = chooseCorrectionParameterMode(app)
            
            modes = {'Heavy','Medium','Light','Manual'};
            
            initIdx = find(strcmp(modes,app.CosmicCorrectionParameterMode),1);
            
            if isempty(initIdx)
                initIdx = 2; % Medium
            end
            
            [idx,tf] = listdlg( ...
                'PromptString',{ ...
                'Choose correction parameter mode:'; ...
                ''; ...
                'Heavy  = stronger correction for wider spike tails.'; ...
                'Medium = balanced, recommended first choice.'; ...
                'Light  = minimal correction for narrow spikes.'; ...
                'Manual = edit all correction parameters.'}, ...
                'SelectionMode','single', ...
                'ListString',modes, ...
                'InitialValue',initIdx, ...
                'Name','Correction parameter mode', ...
                'ListSize',[360 130]);
            
            if ~tf
                modeValue = '';
                return
            end
            
            modeValue = modes{idx};
        end
        
        
        function params = editCorrectionParametersManual(app,initialParams)
            
            if nargin < 2 || isempty(initialParams)
                initialParams = app.CorrectParams;
            end
            
            guide = { ...
                'MANUAL COSMIC CORRECTION PARAMETERS'; ...
                '================================'; ...
                ''; ...
                'ExpandPoints:'; ...
                '  Add extra spectral points around the detected spike.'; ...
                '  0 = correct only detected spike points.'; ...
                '  1 = also correct one point on each side.'; ...
                ''; ...
                'SupportPoints:'; ...
                '  Number of clean neighboring points used for interpolation.'; ...
                '  3-4 is usually safe.'; ...
                '  5-6 is more stable but may cross real Raman bands.'; ...
                ''; ...
                'MedianWindow:'; ...
                '  Used by Local median method.'; ...
                '  7 = mild, 9-11 = stronger smoothing.'; ...
                ''; ...
                'Specific cases:'; ...
                '  Very narrow spike: ExpandPoints=0, SupportPoints=3.'; ...
                '  Spike tail remains: ExpandPoints=1, SupportPoints=4-5.'; ...
                '  Noisy spectrum: Local median, MedianWindow=9 or 11.'; ...
                '  Raman band nearby: keep ExpandPoints small and avoid Spline.'};
            
            uialert(app.UIFigure,guide,'Manual correction guide','Icon','info');
            
            prompts = { ...
                'ExpandPoints. Add extra points around spike. 0 safe, 1 stronger.', ...
                'SupportPoints. Clean points on each side for interpolation. Usually 3-5.', ...
                'MedianWindow. Used by Local median. Usually 7-11.'};
            
            defaults = { ...
                num2str(initialParams.ExpandPoints), ...
                num2str(initialParams.SupportPoints), ...
                num2str(initialParams.MedianWindow)};
            
            answ = inputdlg(prompts, ...
                ['Manual correction parameters: ' char(app.MethodsDropDown_2.Value)], ...
                [1 105], ...
                defaults);
            
            if isempty(answ)
                params = [];
                return
            end
            
            params = struct();
            params.Method = string(app.MethodsDropDown_2.Value);
            params.ExpandPoints = round(str2double(answ{1}));
            params.SupportPoints = round(str2double(answ{2}));
            params.MedianWindow = round(str2double(answ{3}));
            
            params = app.sanitizeCorrectionParams(params);
        end
        
        function ok = chooseCosmicDetectionWNMask(app)
            
            ok = false;
            
            if isempty(app.Wavenumber)
                return
            end
            
            wn = double(app.Wavenumber(:));
            nWn = numel(wn);
            
            options = { ...
                'Full WN', ...
                'Analysis WN', ...
                'Exclude ranges', ...
                'Manual include', ...
                'Cancel'};
            
            [idx,tf] = listdlg( ...
                'PromptString',{ ...
                'Choose wavenumber range for cosmic detection:'; ...
                ''; ...
                'Full WN = detect over the entire spectrum.'; ...
                'Analysis WN = detect only in a main biological range.'; ...
                'Exclude ranges = full spectrum except selected WN intervals.'; ...
                'Manual include = detect only inside selected WN intervals.'}, ...
                'SelectionMode','single', ...
                'ListString',options, ...
                'InitialValue',1, ...
                'Name','Cosmic detection wavenumber range', ...
                'ListSize',[430 160]);
            
            if ~tf
                return
            end
            
            choice = options{idx};
            
            if strcmp(choice,'Cancel')
                return
            end
            
            wnMask = true(nWn,1);
            includeRanges = [];
            excludeRanges = [];
            
            if strcmp(choice,'Full WN')
                
                wnMask = true(nWn,1);
                app.CosmicWNMode = 'Full wavenumber range';
                
            elseif strcmp(choice,'Analysis WN')
                
                answer = inputdlg( ...
                    {'Analysis WN low, cm^-1:', ...
                    'Analysis WN high, cm^-1:'}, ...
                    'Analysis wavenumber range', ...
                    [1 70], ...
                    {'600','3100'});
                
                if isempty(answer)
                    return
                end
                
                lo = str2double(answer{1});
                hi = str2double(answer{2});
                
                if isnan(lo) || isnan(hi)
                    uialert(app.UIFigure,'Invalid analysis WN range.','WN range error');
                    return
                end
                
                if lo > hi
                    tmp = lo;
                    lo = hi;
                    hi = tmp;
                end
                
                includeRanges = [lo hi];
                wnMask = wn >= lo & wn <= hi;
                app.CosmicWNMode = sprintf('Analysis WN %.0f-%.0f cm^-1',lo,hi);
                
            elseif strcmp(choice,'Exclude ranges')
                
                answer = inputdlg( ...
                    {'Exclude WN ranges, cm^-1. Example: 1800-2700; 3200-4000'}, ...
                    'Exclude wavenumber ranges', ...
                    [3 85], ...
                    {'1800-2700'});
                
                if isempty(answer)
                    return
                end
                
                excludeRanges = app.parseWNRanges(answer{1});
                
                if isempty(excludeRanges)
                    uialert(app.UIFigure,'No valid exclude range was entered.','WN range warning');
                    return
                end
                
                wnMask = true(nWn,1);
                
                for i = 1:size(excludeRanges,1)
                    lo = excludeRanges(i,1);
                    hi = excludeRanges(i,2);
                    wnMask(wn >= lo & wn <= hi) = false;
                end
                
                app.CosmicWNMode = 'Full WN except excluded ranges';
                
            elseif strcmp(choice,'Manual include')
                
                answer = inputdlg( ...
                    {'Include WN ranges, cm^-1. Example: 600-1800; 2800-3100'}, ...
                    'Manual include wavenumber ranges', ...
                    [3 85], ...
                    {'600-1800; 2800-3100'});
                
                if isempty(answer)
                    return
                end
                
                includeRanges = app.parseWNRanges(answer{1});
                
                if isempty(includeRanges)
                    uialert(app.UIFigure,'No valid include range was entered.','WN range warning');
                    return
                end
                
                wnMask = false(nWn,1);
                
                for i = 1:size(includeRanges,1)
                    lo = includeRanges(i,1);
                    hi = includeRanges(i,2);
                    wnMask = wnMask | (wn >= lo & wn <= hi);
                end
                
                app.CosmicWNMode = 'Manual included WN ranges';
            end
            
            if nnz(wnMask) < 3
                uialert(app.UIFigure, ...
                    'Selected WN mask contains fewer than 3 spectral points.', ...
                    'Invalid WN mask');
                return
            end
            
            app.CosmicWNMask = logical(wnMask(:));
            app.CosmicWNIncludeRanges = includeRanges;
            app.CosmicWNExcludeRanges = excludeRanges;
            
            selectedWN = wn(app.CosmicWNMask);
            
            app.setText({ ...
                'COSMIC WN RANGE SELECTED'; ...
                '================================'; ...
                ''; ...
                ['WN mode: ' app.CosmicWNMode]; ...
                sprintf('Total spectral points: %d',nWn); ...
                sprintf('Detection spectral points: %d',nnz(app.CosmicWNMask)); ...
                sprintf('WN min/max selected: %.2f - %.2f cm^-1', ...
                min(selectedWN), max(selectedWN)); ...
                ''; ...
                'Cosmic detection will only search inside selected WN points.'});
            
            ok = true;
        end
        
        
        function ranges = parseWNRanges(app,txt)
            
            txt = char(txt);
            
            % Normalize separators.
            txt = strrep(txt,char(8211),'-');   % en dash
            txt = strrep(txt,char(8212),'-');   % em dash
            txt = strrep(txt,',',';');
            
            parts = regexp(txt,';','split');
            
            ranges = [];
            
            for i = 1:numel(parts)
                
                s = strtrim(parts{i});
                
                if isempty(s)
                    continue
                end
                
                % Preferred pattern: positive WN range with separators.
                tok = regexp(s, ...
                    '^\s*(\d+(?:\.\d+)?)\s*(?:-|:|to)\s*(\d+(?:\.\d+)?)\s*$', ...
                    'tokens', ...
                    'once');
                
                if isempty(tok)
                    % Fallback: collect positive numbers only.
                    nums = regexp(s,'\d+(?:\.\d+)?','match');
                    
                    if numel(nums) < 2
                        continue
                    end
                    
                    lo = str2double(nums{1});
                    hi = str2double(nums{2});
                else
                    lo = str2double(tok{1});
                    hi = str2double(tok{2});
                end
                
                if isnan(lo) || isnan(hi)
                    continue
                end
                
                if lo > hi
                    tmp = lo;
                    lo = hi;
                    hi = tmp;
                end
                
                ranges(end+1,:) = [lo hi]; %#ok<AGROW>
            end
            
            % Remove duplicate rows if any.
            if ~isempty(ranges)
                ranges = unique(ranges,'rows','stable');
            end
        end


        function initializeCandidatePaging(app)
            
            displayIdx = app.getCandidateTreeDisplayIndices();
            
            if isempty(displayIdx)
                app.CurrentCandidatePage = 1;
                app.CandidateNumPages = 1;
                app.CandidateVisibleIndices = [];
                app.CandidateSummary = app.computeCandidateSummary();
                return
            end
            
            n = numel(displayIdx);
            
            app.CandidatePageSize = min(max(50,round(app.CandidatePageSize)),500);
            app.CandidateNumPages = max(1,ceil(n / app.CandidatePageSize));
            app.CurrentCandidatePage = max(1,min(app.CurrentCandidatePage,app.CandidateNumPages));
            
            app.CandidateSummary = app.computeCandidateSummary();
            app.CandidateVisibleIndices = app.getCandidatePageIndices(app.CurrentCandidatePage);
        end
        
        
        function idx = getCandidatePageIndices(app,pageNumber)
            
            displayIdx = app.getCandidateTreeDisplayIndices();
            
            if isempty(displayIdx)
                idx = [];
                return
            end
            
            n = numel(displayIdx);
            
            pageNumber = max(1,min(app.CandidateNumPages,round(pageNumber)));
            
            i1 = (pageNumber-1)*app.CandidatePageSize + 1;
            i2 = min(n,pageNumber*app.CandidatePageSize);
            
            idx = displayIdx(i1:i2);
            idx = idx(:);
        end
        
        
        function S = computeCandidateSummary(app)
            
            T = app.CandidateTable;
            
            S = struct();
            
            if isempty(T)
                S.Total = 0;
                return
            end
            
            S.Total = height(T);
            S.Accepted = nnz(T.Accepted);
            S.Ignored = nnz(T.Reviewed & ~T.Accepted);
            S.Pending = nnz(~T.Reviewed);
            S.Corrected = nnz(T.Corrected);
            
            S.ScoreMin = min(T.Score);
            S.ScoreMedian = median(T.Score,'omitnan');
            S.ScoreMax = max(T.Score);
            
            S.NarrowWidth1 = nnz((T.KEnd - T.KStart + 1) <= 1);
            S.NarrowWidth2 = nnz((T.KEnd - T.KStart + 1) <= 2);
            S.WidthMax = max(T.KEnd - T.KStart + 1);
            
            S.WNMin = min(T.WN);
            S.WNMax = max(T.WN);
            
            [ny,nx,~] = size(app.CubeOriginal);
            lin = sub2ind([ny nx],T.Y,T.X);
            countVec = accumarray(lin,1,[ny*nx 1],@sum,0);
            S.CountMap = reshape(countVec,ny,nx);
        end
        
        
        function moveCosmicTreeSelection(app, directionValue)
            
            if isempty(app.CandidateTable)
                return
            end
            
            directionValue = ...
                sign(directionValue);
            
            if directionValue == 0
                return
            end
            
            nodes = ...
                app.Tree.SelectedNodes;
            
            if isempty(nodes)
                
                idx = ...
                    app.getCandidatePageIndices( ...
                    app.CurrentCandidatePage);
                
                if isempty(idx)
                    return
                end
                
                if directionValue > 0
                    targetCandidate = idx(1);
                else
                    targetCandidate = idx(end);
                end
                
                app.selectCandidateAndDisplay(targetCandidate);
                return
            end
            
            nodeData = ...
                nodes(1).NodeData;
            
            if isempty(nodeData) || ...
                    ~isstruct(nodeData) || ...
                    ~isfield(nodeData, 'Type')
                
                return
            end
            
            switch char(nodeData.Type)
                
                case 'candidate'
                    
                    currentIndex = ...
                        nodeData.Index;
                    
                    displayIndices = ...
                        app.getCandidateTreeDisplayIndices();
                    
                    position = ...
                        find(displayIndices == currentIndex, 1);
                    
                    if isempty(position)
                        return
                    end
                    
                    newPosition = ...
                        position + directionValue;
                    
                    if newPosition < 1 || ...
                            newPosition > numel(displayIndices)
                        
                        return
                    end
                    
                    targetCandidate = ...
                        displayIndices(newPosition);
                    
                    targetPosition = ...
                        find(displayIndices == targetCandidate, 1);
                    
                    targetPage = ...
                        ceil(targetPosition / app.CandidatePageSize);
                    
                    if targetPage ~= app.CurrentCandidatePage
                        
                        app.CurrentCandidatePage = ...
                            targetPage;
                        
                        app.populateCandidateTree(false);
                    end
                    
                    app.selectCandidateAndDisplay(targetCandidate);
                    
                case {'page', 'current_page'}
                    
                    if isfield(nodeData, 'Page')
                        currentPage = ...
                            nodeData.Page;
                    else
                        currentPage = ...
                            app.CurrentCandidatePage;
                    end
                    
                    targetPage = ...
                        currentPage + directionValue;
                    
                    targetPage = ...
                        max(1, min(app.CandidateNumPages, targetPage));
                    
                    if targetPage == currentPage
                        return
                    end
                    
                    app.CurrentCandidatePage = ...
                        targetPage;
                    
                    app.populateCandidateTree(false);
                    
                    pageIndices = ...
                        app.getCandidatePageIndices(targetPage);
                    
                    if isempty(pageIndices)
                        return
                    end
                    
                    if directionValue > 0
                        targetCandidate = ...
                            pageIndices(1);
                    else
                        targetCandidate = ...
                            pageIndices(end);
                    end
                    
                    app.selectCandidateAndDisplay(targetCandidate);
            end
        end
        
        
        function selectCandidateAndDisplay(app, candidateIndex)
            
            app.selectCandidateNode(candidateIndex);
            
            app.displayCandidate(candidateIndex);
            
            drawnow;
        end
        
        
        function idx = getAutomaticCandidatePageIndices(app, pageNumber)
            
            idx = [];
            
            if isempty(app.CandidateTable) || ...
                    height(app.CandidateTable) == 0
                return
            end
            
            app.CandidateTable = ...
                app.ensureCosmicCandidateTableSchema( ...
                app.CandidateTable);
            
            % =============================================================
            % USE ALL REAL AUTOMATIC CANDIDATES
            %
            % Do not depend on CandidateVisibleIndices because that variable
            % contains only the page currently displayed.
            % =============================================================
            
            allIndices = ...
                (1:height(app.CandidateTable))';
            
            automaticMask = ...
                ~strcmpi( ...
                string(app.CandidateTable.Source), ...
                "Manual");
            
            automaticIndices = ...
                allIndices(automaticMask);
            
            if isempty(automaticIndices)
                return
            end
            
            % Preserve CandidateTable ordering:
            % the detector already sorts automatic candidates by descending score.
            pageSize = ...
                max(1, round(app.CandidatePageSize));
            
            actualNumPages = ...
                ceil(numel(automaticIndices) / pageSize);
            
            app.CandidateNumPages = ...
                max(1, actualNumPages);
            
            pageNumber = ...
                max( ...
                1, ...
                min( ...
                app.CandidateNumPages, ...
                round(pageNumber)));
            
            firstPosition = ...
                (pageNumber - 1) * pageSize + 1;
            
            lastPosition = ...
                min( ...
                numel(automaticIndices), ...
                pageNumber * pageSize);
            
            if firstPosition > lastPosition
                return
            end
            
            idx = ...
                automaticIndices(firstPosition:lastPosition);
            
            idx = ...
                idx(:);
        end


        function newRow = buildManualCandidateRow( ...
                app, y, x, selectedIndices)
            
            newRow = ...
                table();
            
            spectrum = ...
                double(squeeze(app.CubeOriginal(y, x, :)));
            
            localWindow = ...
                max(7, round(app.DetectionParams.LocalWindow));
            
            if mod(localWindow, 2) == 0
                localWindow = localWindow + 1;
            end
            
            localMedian = ...
                movmedian( ...
                spectrum, ...
                localWindow, ...
                'omitnan');
            
            residual = ...
                spectrum - localMedian;
            
            regionResidual = ...
                residual(selectedIndices);
            
            [peakValue, localPeakPosition] = ...
                max(regionResidual);
            
            residualSigma = ...
                app.robustSigmaSingle( ...
                residual(residual > 0));
            
            if ~isfinite(peakValue) || ...
                    peakValue < 2.5 * residualSigma
                
                return
            end
            
            kPeak = ...
                selectedIndices(localPeakPosition);
            
            supportThreshold = ...
                max( ...
                0.10 * peakValue, ...
                1.5 * residualSigma);
            
            kStart = ...
                kPeak;
            
            kEnd = ...
                kPeak;
            
            while kStart > selectedIndices(1) && ...
                    residual(kStart - 1) >= supportThreshold
                
                kStart = ...
                    kStart - 1;
            end
            
            while kEnd < selectedIndices(end) && ...
                    residual(kEnd + 1) >= supportThreshold
                
                kEnd = ...
                    kEnd + 1;
            end
            
            eventIdx = ...
                (kStart:kEnd)';
            
            halfHeight = ...
                0.5 * peakValue;
            
            halfIndices = ...
                eventIdx(residual(eventIdx) >= halfHeight);
            
            if isempty(halfIndices)
                halfIndices = kPeak;
            end
            
            fwhmCm1 = ...
                abs( ...
                app.Wavenumber(halfIndices(end)) - ...
                app.Wavenumber(halfIndices(1))) + ...
                app.SpectralSampling;
            
            supportWidthCm1 = ...
                abs( ...
                app.Wavenumber(kEnd) - ...
                app.Wavenumber(kStart)) + ...
                app.SpectralSampling;
            
            ratio = ...
                fwhmCm1 / max(app.SpectralResolution, eps);
            
            [peakLocations, peakValues] = ...
                app.findEventLocalPeaks( ...
                residual, ...
                kStart, ...
                kEnd);
            
            if numel(peakLocations) >= 2
                
                [~, order] = ...
                    sort(peakValues, 'descend');
                
                peakLocations = ...
                    peakLocations(order);
                
                kPeak = ...
                    peakLocations(1);
                
                kPeak2 = ...
                    peakLocations(2);
                
                eventType = ...
                    "Doublet";
                
            else
                
                kPeak2 = ...
                    NaN;
                
                if ratio > app.CosmicMaxAutomaticWidthR
                    eventType = ...
                        "Broad suspicious";
                else
                    eventType = ...
                        "Single";
                end
            end
            
            score = ...
                peakValue / max(residualSigma, eps);
            
            neighborDiscordance = ...
                app.computeSpatialNeighborDiscordance( ...
                y, ...
                x, ...
                kPeak);
            
            newRow = table( ...
                0, ...
                y, ...
                x, ...
                kStart, ...
                kEnd, ...
                kStart, ...
                kPeak, ...
                kPeak2, ...
                kEnd, ...
                app.Wavenumber(kPeak), ...
                app.safeWNValue(kPeak2), ...
                string(eventType), ...
                fwhmCm1, ...
                supportWidthCm1, ...
                ratio, ...
                score, ...
                score, ...
                neighborDiscordance, ...
                score, ...
                "Manual review", ...
                true, ...
                true, ...
                false, ...
                'VariableNames', ...
                app.makeEmptyCosmicCandidateTable().Properties.VariableNames);
        end
        
        
        function lines = makeCandidateSummaryText(app)
            
            S = app.CandidateSummary;
            
            if isempty(fieldnames(S)) || ~isfield(S,'Total') || S.Total == 0
                lines = { ...
                    'COSMIC DETECTION SUMMARY'; ...
                    ''; ...
                    'No cosmic candidate was detected.'};
                return
            end
            
            lines = { ...
                'COSMIC DETECTION SUMMARY'; ...
                '================================'; ...
                ''; ...
                ['Scan: ' app.CurrentScanName]; ...
                sprintf('WN mode: %s',app.CosmicWNMode); ...
                sprintf('Detection WN points: %d / %d',nnz(app.CosmicWNMask),numel(app.Wavenumber)); ...
                ''; ...
                sprintf('Total candidates detected: %d',S.Total); ...
                sprintf('Current page: %d / %d',app.CurrentCandidatePage,app.CandidateNumPages); ...
                sprintf('Page size: %d candidates',app.CandidatePageSize); ...
                ''; ...
                'Review status:'; ...
                sprintf('  Pending: %d',S.Pending); ...
                sprintf('  Accepted: %d',S.Accepted); ...
                sprintf('  Ignored: %d',S.Ignored); ...
                sprintf('  Corrected: %d',S.Corrected); ...
                ''; ...
                'Score summary:'; ...
                sprintf('  Min / Median / Max: %.2f / %.2f / %.2f',S.ScoreMin,S.ScoreMedian,S.ScoreMax); ...
                ''; ...
                'Spike width summary:'; ...
                sprintf('  Width <= 1 point: %d',S.NarrowWidth1); ...
                sprintf('  Width <= 2 points: %d',S.NarrowWidth2); ...
                sprintf('  Max width: %d',S.WidthMax); ...
                ''; ...
                'Important:'; ...
                '  All candidates are kept in CandidateTable.'; ...
                '  The Tree only displays the current page to keep the app fast.'; ...
                '  Agree page affects only the current page.'};
        end


        function plotCandidateSummaryView(app)
            
            if isempty(app.CandidateTable)
                app.clearAxes();
                return
            end
            
            S = app.CandidateSummary;
            
            if ~isfield(S,'CountMap')
                S = app.computeCandidateSummary();
                app.CandidateSummary = S;
            end
            
            ax1 = app.UIAxes;
            app.resetImageAxis(ax1);
            imagesc(ax1,S.CountMap);
            axis(ax1,'image');
            ax1.YDir = 'normal';
            colormap(ax1,'hot');
            colorbar(ax1);
            title(ax1,{app.CurrentScanName,'Cosmic candidate count map'},'Interpreter','none');
            box(ax1,'on');
            
            ax2 = app.UIAxes2;
            app.resetSpectrumAxis(ax2);
            
            histogram(ax2,app.CandidateTable.Score,60);
            xlabel(ax2,'Candidate score');
            ylabel(ax2,'Count');
            title(ax2,'Cosmic candidate score distribution');
            box(ax2,'on');
            
            app.setText(app.makeCandidateSummaryText());
        end
        
        
        function pageIdx = getSelectedPageIndex(app)
            pageIdx = [];
            nodes = app.Tree.SelectedNodes;
            for i = 1:numel(nodes)
                nd = nodes(i);
                if isempty(nd.NodeData) || ~isstruct(nd.NodeData) || ~isfield(nd.NodeData,'Type')
                    continue
                end
                if strcmp(nd.NodeData.Type,'page') || strcmp(nd.NodeData.Type,'current_page')
                    pageIdx = nd.NodeData.Page;
                    return
                end
            end
        end
        
        function loadSavedPretreatmentFile(app, stage)
            
            app.initializeProjectPaths();
            
            stage = ...
                lower(strtrim(char(stage)));
            
            % =============================================================
            % START FOLDER
            % =============================================================
            
            switch stage
                
                case 'cosmic'
                    
                    startFolder = ...
                        app.Paths.CosmicDataRoot;
                    
                    titleText = ...
                        'Select saved Cosmic MAT file';
                    
                case 'artifact'
                    
                    startFolder = ...
                        app.Paths.ArtifactDataRoot;
                    
                    titleText = ...
                        'Select saved Artifact MAT file';
                    
                case 'background'
                    
                    startFolder = ...
                        app.Paths.BackgroundDataRoot;
                    
                    titleText = ...
                        'Select saved Background MAT file';
                    
                otherwise
                    
                    error( ...
                        'Unknown pretreatment resume stage: %s', ...
                        stage);
            end
            
            % When no current scan has been selected yet,
            % browse from the common workspace root.
            if isempty(startFolder) || ...
                    ~isfolder(startFolder)
                
                startFolder = ...
                    app.ScanWorkspaceRoot;
            end
            
            % =============================================================
            % SELECT CHECKPOINT
            % =============================================================
            
            [fileName, pathName] = ...
                uigetfile( ...
                {'*.mat', 'MAT files (*.mat)'}, ...
                titleText, ...
                startFolder);
            
            if isequal(fileName, 0)
                return
            end
            
            savedPath = ...
                fullfile( ...
                pathName, ...
                fileName);
            
            S = ...
                load(savedPath);
            
            % =============================================================
            % V2 LINKED CHECKPOINT HYDRATION
            %
            % Artifact / Background files no longer contain a Raman cube.
            % Temporarily load the materialized Cosmic/source cube into RAM
            % before calling the existing resume functions.
            % =============================================================
            
            S = ...
                app.hydrateLinkedStageForResume(S);
            
            % =============================================================
            % CLEAR CURRENT STATE
            % =============================================================
            
            app.resetLoadedStateBeforeResume();
            
            resumedType = '';
            
            % =============================================================
            % RESUME LATEST AVAILABLE STAGE
            % =============================================================
            
            if isfield(S, 'RamanDataBackground')
                
                app.resumeBackgroundSavedData( ...
                    S, ...
                    savedPath);
                
                resumedType = ...
                    'Resume Background saved';
                
            elseif isfield(S, 'RamanDataArtifact')
                
                app.resumeArtifactSavedData( ...
                    S, ...
                    savedPath);
                
                resumedType = ...
                    'Resume Artifact saved';
                
            elseif isfield(S, 'RamanDataCosmic')
                
                app.resumeCosmicSavedData( ...
                    S, ...
                    savedPath);
                
                resumedType = ...
                    'Resume Cosmic saved';
                
            elseif isfield(S, 'PretreatState')
                
                app.resumePretreatStateOnly( ...
                    S, ...
                    savedPath);
                
                resumedType = ...
                    'Resume pretreatment state';
                
            else
                
                error( ...
                    ['The selected MAT file is not a recognized ' ...
                    'scanpretreat checkpoint.']);
            end
            
            % =============================================================
            % VALIDATE
            % =============================================================
            
            if isempty(app.CubeOriginal)
                
                error( ...
                    'Resume completed but no Raman cube is available.');
            end
            
            if isempty(app.Wavenumber)
                
                error( ...
                    'Resume completed but no wavenumber axis is available.');
            end
            
            if isempty(app.CurrentScanName)
                
                [~, fallbackName] = ...
                    fileparts(savedPath);
                
                app.CurrentScanName = ...
                    fallbackName;
            end
            
            % =============================================================
            % SEND ACTIVE DATA TO SCANSPECTRA
            % =============================================================
            
            sentOK = ...
                app.sendActiveLoadedScanToMainApp( ...
                resumedType);
            
            if sentOK
                
                sendText = ...
                    'Restored scan sent to scanspectra successfully.';
                
            else
                
                sendText = ...
                    'Restored scan was not sent to scanspectra.';
            end
            
            % =============================================================
            % DISPLAY
            % =============================================================
            
            statusLines = { ...
                ''; ...
                'DOWNSTREAM TRANSFER'; ...
                '================================'; ...
                sendText; ...
                ['Source action: ' resumedType]; ...
                ['Active scan: ' app.CurrentScanName]; ...
                ['Checkpoint: ' savedPath]};
            
            if strcmp(stage, 'background')
                
                oldText = ...
                    app.TextArea_3.Value;
                
                if ischar(oldText) || isstring(oldText)
                    oldText = cellstr(oldText);
                end
                
                app.TextArea_3.Value = ...
                    [oldText(:); statusLines];
                
            elseif strcmp(stage, 'artifact')
                
                oldText = ...
                    app.TextArea_2.Value;
                
                if ischar(oldText) || isstring(oldText)
                    oldText = cellstr(oldText);
                end
                
                app.TextArea_2.Value = ...
                    [oldText(:); statusLines];
                
            else
                
                oldText = ...
                    app.TextArea.Value;
                
                if ischar(oldText) || isstring(oldText)
                    oldText = cellstr(oldText);
                end
                
                app.TextArea.Value = ...
                    [oldText(:); statusLines];
            end
            
            drawnow limitrate;
            
        end
        
        
        function resetLoadedStateBeforeResume(app)
            
            % =========================================================
            % GENERAL SCAN STATE
            % =========================================================
            
            app.ScanList = ...
                struct('Name', {}, 'MatPath', {}, 'Folder', {});
            
            app.CurrentScanIndex = 1;
            app.CurrentCandidateIndex = NaN;
            
            % =========================================================
            % COSMIC STATE
            % =========================================================
            
            app.TempMask = logical.empty;
            app.TempScore = single.empty;
            app.ConfirmedMask = logical.empty;
            app.CandidateTable = table();
            
            app.CorrectionTrialMode = false;
            app.CorrectionReady = false;
            app.CorrectParamsChecked = false;
            
            app.CandidatePageSize = 200;
            app.CurrentCandidatePage = 1;
            app.CandidateNumPages = 1;
            app.CandidateVisibleIndices = [];
            app.CandidateSummary = struct();
            
            app.CosmicConfirmed = false;
            app.LastCosmicStatePath = '';
            app.LastCosmicEvidenceFolder = '';
            
            % =========================================================
            % ARTIFACT STATE
            % =========================================================
            
            app.ArtifactReady = false;
            app.ArtifactConfirmed = false;
            
            app.ArtifactMask2D = logical.empty;
            app.ValidPixelMask2D = logical.empty;
            
            app.ArtifactDisplayMapRaw = [];
            app.ArtifactDisplayMapClean = [];
            app.ArtifactDisplayLabel = '';
            
            app.ArtifactStripeScoreMap = [];
            app.ArtifactBandConsistencyMap = [];
            app.ArtifactSpectralZMap = [];
            
            app.ArtifactStrongSeedMask = logical.empty;
            app.ArtifactWeakSupportMask = logical.empty;
            app.ArtifactHysteresisMask = logical.empty;
            app.ArtifactRowBandMask = logical.empty;
            
            app.ArtifactRowEvidence = struct();
            app.ArtifactTable = table();
            
            % =========================================================
            % BACKGROUND STATE
            % =========================================================
            
            app.BackgroundReady = false;
            app.BackgroundValidated = false;
            app.BackgroundConfirmed = false;
            
            app.BackgroundInputCube = [];
            app.BackgroundValidMask2D = logical.empty;
            app.BackgroundArtifactMask2D = logical.empty;
            
            app.BackgroundPreviewMap = [];
            app.BackgroundPreviewLabel = '';
            
            app.BackgroundLabelMap = [];
            app.BackgroundConfidenceMap = [];
            
            app.BackgroundClusters = struct( ...
                'ID', {}, ...
                'Name', {}, ...
                'ParentID', {}, ...
                'PixelIdx', {}, ...
                'Class', {}, ...
                'Active', {}, ...
                'MeanSpectrum', {}, ...
                'StdSpectrum', {}, ...
                'Metrics', {});
            
            app.BackgroundNextClusterID = 1;
            app.BackgroundSelectedClusterIDs = [];
            
            app.BackgroundCellMask2D = logical.empty;
            app.BackgroundBackgroundMask2D = logical.empty;
            app.BackgroundUncertainMask2D = logical.empty;
            app.BackgroundUnassignedMask2D = logical.empty;
            
            app.BackgroundManualClassMap = uint8.empty;
            
            app.BackgroundNumPCUsed = 0;
            app.BackgroundExplained = [];
            app.BackgroundPCAcoeff = [];
            app.BackgroundPCAmu = [];
            
            app.BackgroundAllScores = [];
            app.BackgroundMetricLabels = [];
            app.BackgroundMetricPixelIdx = [];
            app.BackgroundCentroids = [];
            
            app.BackgroundTrainingLocalIdx = [];
            app.BackgroundTrainingLinearIdx = [];
            
            app.BackgroundSilhouetteValues = [];
            app.BackgroundPixelStability = [];
            
            app.BackgroundGlobalMetrics = struct();
            
            app.BackgroundClusterMetrics = struct( ...
                'ClusterID', {}, ...
                'ClusterName', {}, ...
                'PixelCount', {}, ...
                'PixelFraction', {}, ...
                'MeanSilhouette', {}, ...
                'MedianSilhouette', {}, ...
                'P10Silhouette', {}, ...
                'NegativeSilhouetteFraction', {}, ...
                'MedianConfidence', {}, ...
                'LowConfidenceFraction', {}, ...
                'MedianDistance', {}, ...
                'P90Distance', {}, ...
                'NormalizedDispersion', {}, ...
                'MedianSpectralAngle', {}, ...
                'P90SpectralAngle', {}, ...
                'Stability', {}, ...
                'ConnectedComponents', {}, ...
                'LargestComponentFraction', {}, ...
                'IsolatedPixelFraction', {}, ...
                'SplitPriority', {}, ...
                'SplitLevel', {}, ...
                'Recommendation', {}, ...
                'Reasons', {});
            
            app.BackgroundLastSplitTrial = struct();
            
            app.BackgroundUndoStack = {};
            app.BackgroundRedoStack = {};
            
            app.BackgroundLastSavedPath = '';
        end
        
        
        function resumeCosmicSavedData(app, S, savedPath)
            
            % =============================================================
            % 1. VALIDATE SAVED COSMIC DATA
            % =============================================================
            
            if ~isfield(S, 'RamanDataCosmic') || ...
                    ~isstruct(S.RamanDataCosmic)
                
                error( ...
                    'The selected MAT file does not contain RamanDataCosmic.');
            end
            
            R = ...
                S.RamanDataCosmic;
            
            if ~isfield(R, 'Cube') || ...
                    isempty(R.Cube) || ...
                    ndims(R.Cube) ~= 3
                
                error( ...
                    'RamanDataCosmic does not contain a valid 3-D Raman cube.');
            end
            
            % =============================================================
            % 2. RESTORE ORIGINAL CUBE WHEN AVAILABLE
            %
            % The saved Cosmic cube is the corrected cube.
            % CubeOriginal should remain the original pre-correction cube when
            % the original readable MAT file is still available.
            % =============================================================
            
            originalPath = '';
            
            if isfield(S, 'ProcessingInfo') && ...
                    isstruct(S.ProcessingInfo) && ...
                    isfield(S.ProcessingInfo, 'OriginalMatPath') && ...
                    ~isempty(S.ProcessingInfo.OriginalMatPath)
                
                originalPath = ...
                    char(string(S.ProcessingInfo.OriginalMatPath));
            end
            
            originalLoaded = ...
                false;
            
            if ~isempty(originalPath) && ...
                    exist(originalPath, 'file') == 2
                
                try
                    Sraw = ...
                        load(originalPath);
                    
                    Rraw = ...
                        app.extractRamanStruct(Sraw);
                    
                    if isstruct(Rraw) && ...
                            isfield(Rraw, 'Cube') && ...
                            ~isempty(Rraw.Cube) && ...
                            ndims(Rraw.Cube) == 3 && ...
                            isequal(size(Rraw.Cube), size(R.Cube))
                        
                        app.CubeOriginal = ...
                            double(Rraw.Cube);
                        
                        app.RamanData = ...
                            Rraw;
                        
                        app.CurrentMatPath = ...
                            originalPath;
                        
                        originalLoaded = ...
                            true;
                    end
                    
                catch ME
                    warning( ...
                        'RamanCube:CosmicOriginalRestoreFailed', ...
                        '%s', ...
                        ME.message);
                end
            end
            
            if ~originalLoaded
                
                % Backward-compatible fallback:
                % use the saved Cosmic cube as both original and active cube.
                app.CubeOriginal = ...
                    double(R.Cube);
                
                app.RamanData = ...
                    R;
                
                app.CurrentMatPath = ...
                    savedPath;
            end
            
            app.CorrectedCube = ...
                double(R.Cube);
            
            app.TrialCorrectedCube = ...
                [];
            
            app.CorrectionTrialMode = ...
                false;
            
            % =============================================================
            % 3. VALIDATE CUBE SIZE AND WAVENUMBER
            % =============================================================
            
            [ny, nx, nWn] = ...
                size(app.CorrectedCube);
            
            if ~isequal(size(app.CubeOriginal), [ny nx nWn])
                
                error( ...
                    ['The restored original cube and Cosmic-corrected cube ' ...
                    'do not have matching dimensions.']);
            end
            
            if isfield(R, 'Wavenumber') && ...
                    ~isempty(R.Wavenumber)
                
                app.Wavenumber = ...
                    double(R.Wavenumber(:));
                
            else
                
                app.Wavenumber = ...
                    (1:nWn).';
            end
            
            if numel(app.Wavenumber) ~= nWn
                
                error( ...
                    ['The saved Cosmic wavenumber axis does not match ' ...
                    'the spectral dimension of the Raman cube.']);
            end
            
            % =============================================================
            % 4. RESTORE SCAN METADATA
            % =============================================================
            
            if isfield(S, 'ProcessingInfo') && ...
                    isstruct(S.ProcessingInfo) && ...
                    isfield(S.ProcessingInfo, 'ScanName') && ...
                    ~isempty(S.ProcessingInfo.ScanName)
                
                app.CurrentScanName = ...
                    char(string(S.ProcessingInfo.ScanName));
                
            else
                
                [~, nm] = ...
                    fileparts(savedPath);
                
                app.CurrentScanName = ...
                    nm;
            end
            
            app.CurrentScanIndex = ...
                NaN;
            
            % =============================================================
            % 5. RESTORE COSMIC CANDIDATE STATE
            % =============================================================
            
            if isfield(S, 'CandidateTable') && ...
                    istable(S.CandidateTable)
                
                app.CandidateTable = ...
                    app.ensureCosmicCandidateTableSchema( ...
                    S.CandidateTable);
                
            else
                
                app.CandidateTable = ...
                    app.makeEmptyCosmicCandidateTable();
            end
            
            if isfield(S, 'CosmicMask') && ...
                    isequal(size(S.CosmicMask), [ny nx nWn])
                
                app.ConfirmedMask = ...
                    logical(S.CosmicMask);
                
            elseif isfield(S, 'ConfirmedMask') && ...
                    isequal(size(S.ConfirmedMask), [ny nx nWn])
                
                app.ConfirmedMask = ...
                    logical(S.ConfirmedMask);
                
            elseif ~isempty(app.CandidateTable)
                
                app.ConfirmedMask = ...
                    app.buildMaskFromAcceptedCandidates();
                
            else
                
                app.ConfirmedMask = ...
                    false(ny, nx, nWn);
            end
            
            % =============================================================
            % 6. RESTORE ROI
            % =============================================================
            
            finiteMask = ...
                all(isfinite(app.CorrectedCube), 3);
            
            if isfield(S, 'ROIMask') && ...
                    isequal(size(S.ROIMask), [ny nx])
                
                app.CurrentROIMask = ...
                    logical(S.ROIMask) & finiteMask;
                
                app.ROIReady = ...
                    any(app.CurrentROIMask(:)) && ...
                    ~all(app.CurrentROIMask(:));
                
            else
                
                app.CurrentROIMask = ...
                    finiteMask;
                
                app.ROIReady = ...
                    false;
            end
            
            % =============================================================
            % 7. RESTORE DELETED SPECTRA
            % =============================================================
            
            if isfield(S, 'DeletedSpectrumMask2D') && ...
                    isequal(size(S.DeletedSpectrumMask2D), [ny nx])
                
                app.DeletedSpectrumMask2D = ...
                    logical(S.DeletedSpectrumMask2D);
                
            else
                
                app.DeletedSpectrumMask2D = ...
                    false(ny, nx);
            end
            
            % Invalid/non-finite pixels must not be interpreted as manually
            % deleted spectra.
            app.DeletedSpectrumMask2D = ...
                app.DeletedSpectrumMask2D & finiteMask;
            
            if isfield(S, 'DeletedSpectrumTable') && ...
                    istable(S.DeletedSpectrumTable)
                
                app.DeletedSpectrumTable = ...
                    S.DeletedSpectrumTable;
                
            else
                
                app.DeletedSpectrumTable = ...
                    table();
            end
            
            % =============================================================
            % 8. RESTORE DETECTION AND CORRECTION PARAMETERS
            % =============================================================
            
            if isfield(S, 'DetectionParams') && ...
                    isstruct(S.DetectionParams)
                
                app.DetectionParams = ...
                    app.sanitizeDetectionParams( ...
                    S.DetectionParams);
                
            else
                
                app.DetectionParams = ...
                    app.sanitizeDetectionParams( ...
                    app.DetectionParams);
            end
            
            if isfield(S, 'CorrectParams') && ...
                    isstruct(S.CorrectParams)
                
                app.CorrectParams = ...
                    S.CorrectParams;
            end
            
            % =============================================================
            % 9. RESTORE WAVENUMBER SCOPE
            % =============================================================
            
            if isfield(S, 'CosmicWNMask') && ...
                    numel(S.CosmicWNMask) == nWn
                
                app.CosmicWNMask = ...
                    logical(S.CosmicWNMask(:));
                
            else
                
                app.CosmicWNMask = ...
                    true(nWn, 1);
            end
            
            if isfield(S, 'CosmicWNMode') && ...
                    ~isempty(S.CosmicWNMode)
                
                app.CosmicWNMode = ...
                    char(string(S.CosmicWNMode));
                
            else
                
                app.CosmicWNMode = ...
                    'Full wavenumber range';
            end
            
            if isfield(S, 'CosmicWNIncludeRanges')
                
                app.CosmicWNIncludeRanges = ...
                    double(S.CosmicWNIncludeRanges);
                
            else
                
                app.CosmicWNIncludeRanges = ...
                    [];
            end
            
            if isfield(S, 'CosmicWNExcludeRanges')
                
                app.CosmicWNExcludeRanges = ...
                    double(S.CosmicWNExcludeRanges);
                
            else
                
                app.CosmicWNExcludeRanges = ...
                    [];
            end
            
            % =============================================================
            % 10. RESTORE PAGING AND SUMMARY
            % =============================================================
            
            if isfield(S, 'CandidateSummary') && ...
                    isstruct(S.CandidateSummary)
                
                app.CandidateSummary = ...
                    S.CandidateSummary;
                
            else
                
                app.CandidateSummary = ...
                    struct();
            end
            
            if isfield(S, 'CandidatePageSize') && ...
                    isscalar(S.CandidatePageSize) && ...
                    isfinite(S.CandidatePageSize)
                
                app.CandidatePageSize = ...
                    max(1, round(double(S.CandidatePageSize)));
                
            else
                
                app.CandidatePageSize = ...
                    200;
            end
            
            if isfield(S, 'CandidateNumPages') && ...
                    isscalar(S.CandidateNumPages) && ...
                    isfinite(S.CandidateNumPages)
                
                app.CandidateNumPages = ...
                    max(1, round(double(S.CandidateNumPages)));
                
            else
                
                app.CandidateNumPages = ...
                    1;
            end
            
            app.CurrentCandidatePage = ...
                1;
            
            app.CurrentCandidateIndex = ...
                NaN;
            
            app.CandidateVisibleIndices = ...
                [];
            
            % =============================================================
            % 11. RESTORE MANUAL COSMIC ROI STATE
            % =============================================================
            
            if isfield(S, 'ManualCosmicSpatialMask') && ...
                    isequal(size(S.ManualCosmicSpatialMask), [ny nx])
                
                app.ManualCosmicSpatialMask = ...
                    logical(S.ManualCosmicSpatialMask);
                
            else
                
                app.ManualCosmicSpatialMask = ...
                    logical.empty;
            end
            
            if isfield(S, 'ManualCosmicWNMask') && ...
                    numel(S.ManualCosmicWNMask) == nWn
                
                app.ManualCosmicWNMask = ...
                    logical(S.ManualCosmicWNMask(:));
                
            else
                
                app.ManualCosmicWNMask = ...
                    logical.empty;
            end
            
            if isfield(S, 'ManualCosmicROIReady')
                
                app.ManualCosmicROIReady = ...
                    logical(S.ManualCosmicROIReady);
                
            else
                
                app.ManualCosmicROIReady = ...
                    false;
            end
            
            if isfield(S, 'ManualCosmicROICount') && ...
                    isscalar(S.ManualCosmicROICount)
                
                app.ManualCosmicROICount = ...
                    max(0, round(double(S.ManualCosmicROICount)));
                
            else
                
                app.ManualCosmicROICount = ...
                    0;
            end
            
            if isfield(S, 'ManualCosmicROISummary') && ...
                    iscell(S.ManualCosmicROISummary)
                
                app.ManualCosmicROISummary = ...
                    S.ManualCosmicROISummary;
                
            else
                
                app.ManualCosmicROISummary = ...
                    {};
            end
            
            % =============================================================
            % 12. RESTORE AUXILIARY COSMIC STATE
            % =============================================================
            
            if isfield(S, 'AuxCosmicEnabled')
                
                app.AuxCosmicEnabled = ...
                    logical(S.AuxCosmicEnabled);
            end
            
            if isfield(S, 'AuxCosmicRange') && ...
                    numel(S.AuxCosmicRange) == 2
                
                app.AuxCosmicRange = ...
                    double(S.AuxCosmicRange(:)).';
            end
            
            if isfield(S, 'AuxCosmicMask') && ...
                    isequal(size(S.AuxCosmicMask), [ny nx nWn])
                
                app.AuxCosmicMask = ...
                    logical(S.AuxCosmicMask);
                
            else
                
                app.AuxCosmicMask = ...
                    logical.empty;
            end
            
            if isfield(S, 'AuxCosmicCandidateTable') && ...
                    istable(S.AuxCosmicCandidateTable)
                
                app.AuxCosmicCandidateTable = ...
                    S.AuxCosmicCandidateTable;
                
            else
                
                app.AuxCosmicCandidateTable = ...
                    table();
            end
            
            if isfield(S, 'AuxCosmicCorrectedCount') && ...
                    isscalar(S.AuxCosmicCorrectedCount)
                
                app.AuxCosmicCorrectedCount = ...
                    max(0, round(double(S.AuxCosmicCorrectedCount)));
                
            else
                
                app.AuxCosmicCorrectedCount = ...
                    0;
            end
            
            % =============================================================
            % 13. FINAL COSMIC WORKFLOW STATE
            % =============================================================
            
            hasCandidateState = ...
                ~isempty(app.CandidateTable) && ...
                height(app.CandidateTable) > 0;
            
            hasConfirmedMask = ...
                ~isempty(app.ConfirmedMask) && ...
                any(app.ConfirmedMask(:));
            
            app.CorrectionReady = ...
                true;
            
            app.CosmicConfirmed = ...
                hasCandidateState || ...
                hasConfirmedMask;
            
            app.CosmicReviewFinalized = ...
                app.CosmicConfirmed;
            
            app.CorrectParamsChecked = ...
                true;
            
            app.LastDetectionParamsAfterCorrection = ...
                app.DetectionParams;
            
            app.LastDetectionParamSignature = ...
                app.detectionParamSignature( ...
                app.DetectionParams);
            
            % Cosmic resume invalidates unsaved downstream in-memory results.
            app.resetArtifactForRerun();
            
            app.BackgroundReady = ...
                false;
            
            app.BackgroundValidated = ...
                false;
            
            app.BackgroundConfirmed = ...
                false;
            
            % =============================================================
            % 14. REBUILD UI
            % =============================================================
            
            app.TabGroup.SelectedTab = ...
                app.CosmicremovalTab;
            
            app.initializeCandidatePaging();
            
            app.populateCandidateTree(false);
            
            app.plotCosmicBeforeAfterMapReview();
            
            app.setCosmicWorkflowControls('corrected');
            
            app.updateScanInformationBox();
            
            candidateCount = ...
                height(app.CandidateTable);
            
            acceptedCount = ...
                0;
            
            correctedCount = ...
                0;
            
            if candidateCount > 0
                
                if ismember( ...
                        'Accepted', ...
                        app.CandidateTable.Properties.VariableNames)
                    
                    acceptedCount = ...
                        nnz(app.CandidateTable.Accepted);
                end
                
                if ismember( ...
                        'Corrected', ...
                        app.CandidateTable.Properties.VariableNames)
                    
                    correctedCount = ...
                        nnz(app.CandidateTable.Corrected);
                end
            end
            
            app.setText({ ...
                'RESUMED COSMIC SAVED DATA'; ...
                '================================'; ...
                ''; ...
                ['Saved file: ' savedPath]; ...
                ['Scan: ' app.CurrentScanName]; ...
                sprintf('Cube size: %d x %d x %d', ...
                ny, nx, nWn); ...
                sprintf('Candidates: %d', ...
                candidateCount); ...
                sprintf('Accepted: %d', ...
                acceptedCount); ...
                sprintf('Corrected: %d', ...
                correctedCount); ...
                sprintf('Deleted spectra restored: %d', ...
                nnz(app.DeletedSpectrumMask2D)); ...
                ''; ...
                'The Cosmic-corrected cube is loaded as the active cube.'; ...
                'Deleted spectra were restored independently from the ROI.'; ...
                'You may continue to Artifact removal or review Cosmic.'});
            
            drawnow;
        end
        
        

        function resumeArtifactSavedData(app, S, savedPath)
            
            % =============================================================
            % 1. VALIDATE SAVED ARTIFACT DATA
            % =============================================================
            
            if ~isfield(S, 'RamanDataArtifact') || ...
                    ~isstruct(S.RamanDataArtifact)
                
                error( ...
                    'The selected MAT file does not contain RamanDataArtifact.');
            end
            
            R = ...
                S.RamanDataArtifact;
            
            if ~isfield(R, 'Cube') || ...
                    isempty(R.Cube) || ...
                    ndims(R.Cube) ~= 3
                
                error( ...
                    'RamanDataArtifact does not contain a valid 3-D Raman cube.');
            end
            
            % =============================================================
            % 2. RESTORE ACTIVE CUBE
            %
            % RamanDataArtifact.Cube contains the committed Raman cube.
            % Artifact removal does not alter spectra; artifact pixels are
            % excluded through ValidPixelMask2D.
            % =============================================================
            
            app.RamanData = ...
                R;
            
            app.CubeOriginal = ...
                double(R.Cube);
            
            app.CorrectedCube = ...
                double(R.Cube);
            
            app.TrialCorrectedCube = ...
                [];
            
            app.CorrectionTrialMode = ...
                false;
            
            [ny, nx, nWn] = ...
                size(app.CubeOriginal);
            
            % =============================================================
            % 3. RESTORE WAVENUMBER
            % =============================================================
            
            if isfield(R, 'Wavenumber') && ...
                    ~isempty(R.Wavenumber)
                
                app.Wavenumber = ...
                    double(R.Wavenumber(:));
                
            else
                
                app.Wavenumber = ...
                    (1:nWn).';
            end
            
            if numel(app.Wavenumber) ~= nWn
                
                error( ...
                    ['The saved Artifact wavenumber axis does not match ' ...
                    'the spectral dimension of the Raman cube.']);
            end
            
            finiteMask = ...
                all(isfinite(app.CubeOriginal), 3);
            
            % =============================================================
            % 4. RESTORE SCAN METADATA
            % =============================================================
            
            if isfield(S, 'ProcessingInfo') && ...
                    isstruct(S.ProcessingInfo) && ...
                    isfield(S.ProcessingInfo, 'ScanName') && ...
                    ~isempty(S.ProcessingInfo.ScanName)
                
                app.CurrentScanName = ...
                    char(string(S.ProcessingInfo.ScanName));
                
            else
                
                [~, nm] = ...
                    fileparts(savedPath);
                
                app.CurrentScanName = ...
                    nm;
            end
            
            app.CurrentMatPath = ...
                savedPath;
            
            app.CurrentScanIndex = ...
                NaN;
            
            % =============================================================
            % 5. RESTORE COSMIC-LEVEL STATE WHEN INCLUDED
            % =============================================================
            
            if isfield(S, 'CosmicMask') && ...
                    isequal(size(S.CosmicMask), [ny nx nWn])
                
                app.ConfirmedMask = ...
                    logical(S.CosmicMask);
                
            else
                
                app.ConfirmedMask = ...
                    false(ny, nx, nWn);
            end
            
            if isfield(S, 'CandidateTable') && ...
                    istable(S.CandidateTable)
                
                app.CandidateTable = ...
                    app.ensureCosmicCandidateTableSchema( ...
                    S.CandidateTable);
                
            else
                
                app.CandidateTable = ...
                    app.makeEmptyCosmicCandidateTable();
            end
            
            % =============================================================
            % 6. RESTORE ROI
            % =============================================================
            
            if isfield(S, 'ROIMask') && ...
                    isequal(size(S.ROIMask), [ny nx])
                
                app.CurrentROIMask = ...
                    logical(S.ROIMask) & finiteMask;
                
                app.ROIReady = ...
                    any(app.CurrentROIMask(:)) && ...
                    ~all(app.CurrentROIMask(:));
                
            else
                
                app.CurrentROIMask = ...
                    finiteMask;
                
                app.ROIReady = ...
                    false;
            end
            
            % =============================================================
            % 7. RESTORE DELETED SPECTRA
            % =============================================================
            
            if isfield(S, 'DeletedSpectrumMask2D') && ...
                    isequal(size(S.DeletedSpectrumMask2D), [ny nx])
                
                app.DeletedSpectrumMask2D = ...
                    logical(S.DeletedSpectrumMask2D);
                
            else
                
                % Backward compatibility for older Artifact MAT files.
                app.DeletedSpectrumMask2D = ...
                    false(ny, nx);
            end
            
            app.DeletedSpectrumMask2D = ...
                app.DeletedSpectrumMask2D & finiteMask;
            
            if isfield(S, 'DeletedSpectrumTable') && ...
                    istable(S.DeletedSpectrumTable)
                
                app.DeletedSpectrumTable = ...
                    S.DeletedSpectrumTable;
                
            else
                
                app.DeletedSpectrumTable = ...
                    table();
            end
            
            % =============================================================
            % 8. RESTORE ARTIFACT MASK
            % =============================================================
            
            if isfield(S, 'ArtifactMask2D') && ...
                    isequal(size(S.ArtifactMask2D), [ny nx])
                
                app.ArtifactMask2D = ...
                    logical(S.ArtifactMask2D);
                
            else
                
                app.ArtifactMask2D = ...
                    false(ny, nx);
            end
            
            % Artifact mask should only refer to finite pixels.
            app.ArtifactMask2D = ...
                app.ArtifactMask2D & finiteMask;
            
            % =============================================================
            % 9. RESTORE VALID PIXEL MASK
            % =============================================================
            
            if isfield(S, 'ValidPixelMask2D') && ...
                    isequal(size(S.ValidPixelMask2D), [ny nx])
                
                app.ValidPixelMask2D = ...
                    logical(S.ValidPixelMask2D);
                
            else
                
                app.ValidPixelMask2D = ...
                    finiteMask & ...
                    app.CurrentROIMask & ...
                    ~app.ArtifactMask2D;
            end
            
            % Mandatory consistency merge:
            % deleted spectra must never return as valid after resume.
            app.ValidPixelMask2D = ...
                logical(app.ValidPixelMask2D) & ...
                finiteMask & ...
                app.CurrentROIMask & ...
                ~app.ArtifactMask2D & ...
                ~app.DeletedSpectrumMask2D;
            
            % =============================================================
            % 10. RESTORE ARTIFACT DISPLAY MAPS
            % =============================================================
            
            if isfield(S, 'CleanDisplayMap') && ...
                    isequal(size(S.CleanDisplayMap), [ny nx])
                
                app.ArtifactDisplayMapClean = ...
                    double(S.CleanDisplayMap);
                
            else
                
                app.ArtifactDisplayMapClean = ...
                    [];
            end
            
            if isfield(S, 'RawDisplayMap') && ...
                    isequal(size(S.RawDisplayMap), [ny nx])
                
                app.ArtifactDisplayMapRaw = ...
                    double(S.RawDisplayMap);
                
            else
                
                [rawMap, rawLabel] = ...
                    app.buildArtifactDisplayMap( ...
                    app.CorrectedCube, ...
                    2840, ...
                    2940);
                
                app.ArtifactDisplayMapRaw = ...
                    rawMap;
                
                app.ArtifactDisplayLabel = ...
                    rawLabel;
            end
            
            % =============================================================
            % 11. RESTORE ARTIFACT PARAMETERS
            % =============================================================
            
            if isfield(S, 'ArtifactParams') && ...
                    isstruct(S.ArtifactParams)
                
                app.ArtifactParams = ...
                    app.sanitizeArtifactParams( ...
                    S.ArtifactParams);
                
            else
                
                app.ArtifactParams = ...
                    app.getArtifactPresetParams( ...
                    app.ModeDropDown.Value);
            end
            
            if isfield(app.ArtifactParams, 'ModeLabel') && ...
                    ~isempty(app.ArtifactParams.ModeLabel)
                
                app.ArtifactModeName = ...
                    char(string(app.ArtifactParams.ModeLabel));
                
            else
                
                app.ArtifactModeName = ...
                    'Saved artifact state';
            end
            
            % =============================================================
            % 12. RESTORE ARTIFACT COMPONENT TABLE
            % =============================================================
            
            if isfield(S, 'ArtifactTable') && ...
                    istable(S.ArtifactTable)
                
                app.ArtifactTable = ...
                    S.ArtifactTable;
                
            else
                
                app.ArtifactTable = ...
                    table();
            end
            
            % =============================================================
            % 13. RESTORE ARTIFACT SCIENTIFIC MAPS
            % =============================================================
            
            if isfield(S, 'StripeScoreMap') && ...
                    isequal(size(S.StripeScoreMap), [ny nx])
                
                app.ArtifactStripeScoreMap = ...
                    double(S.StripeScoreMap);
                
            else
                
                app.ArtifactStripeScoreMap = ...
                    [];
            end
            
            if isfield(S, 'BandConsistencyMap') && ...
                    isequal(size(S.BandConsistencyMap), [ny nx])
                
                app.ArtifactBandConsistencyMap = ...
                    double(S.BandConsistencyMap);
                
            else
                
                app.ArtifactBandConsistencyMap = ...
                    [];
            end
            
            if isfield(S, 'SpectralZMap') && ...
                    isequal(size(S.SpectralZMap), [ny nx])
                
                app.ArtifactSpectralZMap = ...
                    double(S.SpectralZMap);
                
            else
                
                app.ArtifactSpectralZMap = ...
                    [];
            end
            
            if isfield(S, 'StrongSeedMask') && ...
                    isequal(size(S.StrongSeedMask), [ny nx])
                
                app.ArtifactStrongSeedMask = ...
                    logical(S.StrongSeedMask);
                
            else
                
                app.ArtifactStrongSeedMask = ...
                    logical.empty;
            end
            
            if isfield(S, 'WeakSupportMask') && ...
                    isequal(size(S.WeakSupportMask), [ny nx])
                
                app.ArtifactWeakSupportMask = ...
                    logical(S.WeakSupportMask);
                
            else
                
                app.ArtifactWeakSupportMask = ...
                    logical.empty;
            end
            
            if isfield(S, 'HysteresisMask') && ...
                    isequal(size(S.HysteresisMask), [ny nx])
                
                app.ArtifactHysteresisMask = ...
                    logical(S.HysteresisMask);
                
            else
                
                app.ArtifactHysteresisMask = ...
                    logical.empty;
            end
            
            if isfield(S, 'RowBandMask') && ...
                    isequal(size(S.RowBandMask), [ny nx])
                
                app.ArtifactRowBandMask = ...
                    logical(S.RowBandMask);
                
            else
                
                app.ArtifactRowBandMask = ...
                    logical.empty;
            end
            
            if isfield(S, 'RowEvidence') && ...
                    isstruct(S.RowEvidence)
                
                app.ArtifactRowEvidence = ...
                    S.RowEvidence;
                
            else
                
                app.ArtifactRowEvidence = ...
                    struct();
            end
            
            if isfield(S, 'DisplayInpaintInfo') && ...
                    isstruct(S.DisplayInpaintInfo)
                
                app.ArtifactDisplayInpaintInfo = ...
                    S.DisplayInpaintInfo;
                
            else
                
                app.ArtifactDisplayInpaintInfo = ...
                    struct();
            end
            
            % =============================================================
            % 14. RESTORE AUXILIARY COSMIC STATE
            % =============================================================
            
            if isfield(S, 'AuxCosmicMask') && ...
                    isequal(size(S.AuxCosmicMask), [ny nx nWn])
                
                app.AuxCosmicMask = ...
                    logical(S.AuxCosmicMask);
                
            else
                
                app.AuxCosmicMask = ...
                    logical.empty;
            end
            
            if isfield(S, 'AuxCosmicCandidateTable') && ...
                    istable(S.AuxCosmicCandidateTable)
                
                app.AuxCosmicCandidateTable = ...
                    S.AuxCosmicCandidateTable;
                
            else
                
                app.AuxCosmicCandidateTable = ...
                    table();
            end
            
            if isfield(S, 'AuxCosmicCorrectedCount') && ...
                    isscalar(S.AuxCosmicCorrectedCount)
                
                app.AuxCosmicCorrectedCount = ...
                    max(0, round(double(S.AuxCosmicCorrectedCount)));
                
            else
                
                app.AuxCosmicCorrectedCount = ...
                    0;
            end
            
            % =============================================================
            % 15. REBUILD DISPLAY MAPS WHEN OLD FILES LACK THEM
            % =============================================================
            
            if isempty(app.ArtifactDisplayMapRaw)
                
                [mapImg, mapLabel] = ...
                    app.buildArtifactDisplayMap( ...
                    app.CorrectedCube, ...
                    app.ArtifactParams.DisplayBandLow, ...
                    app.ArtifactParams.DisplayBandHigh);
                
                app.ArtifactDisplayMapRaw = ...
                    mapImg;
                
                app.ArtifactDisplayLabel = ...
                    mapLabel;
            end
            
            if isempty(app.ArtifactDisplayMapClean)
                
                % Backward-compatible fallback.
                % Do not modify the Raman cube merely to rebuild the UI.
                app.ArtifactDisplayMapClean = ...
                    app.ArtifactDisplayMapRaw;
            end
            
            % =============================================================
            % 16. FINAL WORKFLOW STATE
            % =============================================================
            
            app.ArtifactReady = ...
                true;
            
            app.ArtifactConfirmed = ...
                true;
            
            app.CorrectionReady = ...
                true;
            
            app.CosmicConfirmed = ...
                true;
            
            app.CosmicReviewFinalized = ...
                true;
            
            app.CorrectParamsChecked = ...
                true;
            
            % Background must be rebuilt from the restored Artifact result.
            app.BackgroundReady = ...
                false;
            
            app.BackgroundValidated = ...
                false;
            
            app.BackgroundConfirmed = ...
                false;
            
            app.BackgroundInputCube = ...
                [];
            
            app.BackgroundValidMask2D = ...
                logical.empty;
            
            app.BackgroundArtifactMask2D = ...
                logical.empty;
            
            % =============================================================
            % 17. REBUILD UI
            % =============================================================
            
            app.TabGroup.SelectedTab = ...
                app.ArtifactremovalTab;
            
            app.plotArtifactResultOnTab();
            
            app.updateScanInformationBox();
            
            app.setArtifactText({ ...
                'RESUMED ARTIFACT SAVED DATA'; ...
                '================================'; ...
                ''; ...
                ['Saved file: ' savedPath]; ...
                ['Scan: ' app.CurrentScanName]; ...
                sprintf('Cube size: %d x %d x %d', ...
                ny, nx, nWn); ...
                sprintf('Artifact pixels: %d', ...
                nnz(app.ArtifactMask2D)); ...
                sprintf('Deleted spectra restored: %d', ...
                nnz(app.DeletedSpectrumMask2D)); ...
                sprintf('Valid pixels after merge: %d / %d', ...
                nnz(app.ValidPixelMask2D), ...
                numel(app.ValidPixelMask2D)); ...
                sprintf('Artifact components: %d', ...
                height(app.ArtifactTable)); ...
                ''; ...
                'Artifact masks and display maps were restored.'; ...
                'Deleted spectra were removed again from ValidPixelMask2D.'; ...
                ''; ...
                'You may continue to Background removal.'});
            
            drawnow;
        end
        
        
        function resumePretreatStateOnly(app, S, savedPath)
            
            if ~isfield(S, 'PretreatState')
                
                error( ...
                    'The selected state file has no PretreatState.');
            end
            
            state = S.PretreatState;
            
            if ~isfield(state, 'SavedMatPath') || ...
                    isempty(state.SavedMatPath) || ...
                    ~exist(state.SavedMatPath, 'file')
                
                error( ...
                    ['The state file does not contain a valid ' ...
                    'SavedMatPath.']);
            end
            
            S2 = load(state.SavedMatPath);
            
            if isfield(S2, 'RamanDataBackground')
                
                app.resumeBackgroundSavedData( ...
                    S2, ...
                    state.SavedMatPath);
                
            elseif isfield(S2, 'RamanDataArtifact')
                
                app.resumeArtifactSavedData( ...
                    S2, ...
                    state.SavedMatPath);
                
            elseif isfield(S2, 'RamanDataCosmic')
                
                app.resumeCosmicSavedData( ...
                    S2, ...
                    state.SavedMatPath);
                
            else
                
                error( ...
                    ['PretreatState points to an unsupported saved ' ...
                    'MAT file.']);
            end
            
            if isfield(state, 'Stage') && ...
                    strcmpi(char(state.Stage), 'Cosmic')
                
                app.LastCosmicStatePath = savedPath;
            end
        end
        
        function PretreatState = buildCosmicPretreatState(app,savedMatPath,evidenceFolder)
            
            if nargin < 2
                savedMatPath = '';
            end
            
            if nargin < 3
                evidenceFolder = '';
            end
            
            scanID = app.getCurrentScanID();
            
            PretreatState = struct();
            
            PretreatState.Stage = 'Cosmic';
            PretreatState.ScanName = app.CurrentScanName;
            PretreatState.ScanID = scanID;
            PretreatState.ProjectRoot = app.ProjectRoot;
            
            PretreatState.OriginalMatPath = app.CurrentMatPath;
            PretreatState.SavedMatPath = savedMatPath;
            PretreatState.EvidenceFolder = evidenceFolder;
            
            PretreatState.CorrectionReady = app.CorrectionReady;
            PretreatState.CosmicConfirmed = app.CosmicConfirmed;
            PretreatState.CorrectParamsChecked = app.CorrectParamsChecked;
            
            PretreatState.DetectionParams = app.DetectionParams;
            PretreatState.CorrectParams = app.CorrectParams;
            
            PretreatState.CosmicWNMode = app.CosmicWNMode;
            PretreatState.CosmicWNIncludeRanges = app.CosmicWNIncludeRanges;
            PretreatState.CosmicWNExcludeRanges = app.CosmicWNExcludeRanges;
            
            PretreatState.CandidatePageSize = app.CandidatePageSize;
            PretreatState.CandidateNumPages = app.CandidateNumPages;
            PretreatState.CurrentCandidatePage = app.CurrentCandidatePage;
            
            if isempty(app.CandidateTable)
                PretreatState.TotalCandidates = 0;
                PretreatState.AcceptedCandidates = 0;
                PretreatState.IgnoredCandidates = 0;
                PretreatState.PendingCandidates = 0;
                PretreatState.CorrectedCandidates = 0;
            else
                PretreatState.TotalCandidates = height(app.CandidateTable);
                PretreatState.AcceptedCandidates = nnz(app.CandidateTable.Accepted);
                PretreatState.IgnoredCandidates = nnz(app.CandidateTable.Reviewed & ~app.CandidateTable.Accepted);
                PretreatState.PendingCandidates = nnz(~app.CandidateTable.Reviewed);
                PretreatState.CorrectedCandidates = nnz(app.CandidateTable.Corrected);
            end
            
            if ~isempty(app.CurrentROIMask)
                PretreatState.ROIPixels = nnz(app.CurrentROIMask);
                PretreatState.TotalSpatialPixels = numel(app.CurrentROIMask);
            else
                PretreatState.ROIPixels = NaN;
                PretreatState.TotalSpatialPixels = NaN;
            end
            
            if ~isempty(app.ConfirmedMask)
                PretreatState.ConfirmedCosmicVoxels = nnz(app.ConfirmedMask);
            else
                PretreatState.ConfirmedCosmicVoxels = 0;
            end
            SessionMetadata = app.buildSessionMetadata();
            
            PretreatState.User = SessionMetadata.User;
            PretreatState.Email = SessionMetadata.Email;
            PretreatState.Role = SessionMetadata.Role;
            PretreatState.SessionID = SessionMetadata.SessionID;
            PretreatState.SessionLogPath = SessionMetadata.SessionLogPath;
            PretreatState.MainhomeLoginTime = SessionMetadata.MainhomeLoginTime;
            PretreatState.DataInputRoot = SessionMetadata.DataInputRoot;
            PretreatState.DataOutputRoot = SessionMetadata.DataOutputRoot;
            PretreatState.ScanInputRoot = SessionMetadata.ScanInputRoot;
            PretreatState.ScanOutputRoot = SessionMetadata.ScanOutputRoot;
            
            PretreatState.ProcessedDate = datestr(now,'yyyy-mm-dd HH:MM:SS');
        end
        
        function statePath = saveCosmicStateData( ...
                app, ...
                savedMatPath, ...
                evidenceFolder)
            
            if nargin < 3
                evidenceFolder = '';
            end
            
            if isempty(savedMatPath) || ...
                    ~isfile(savedMatPath)
                
                error( ...
                    'Cosmic checkpoint does not exist.');
            end
            
            % =============================================================
            % ONE CHECKPOINT FILE ONLY
            %
            % The main Cosmic MAT already contains:
            %   cube
            %   masks
            %   candidate table
            %   parameters
            %   PretreatState
            %
            % Therefore StatePath intentionally points to the same MAT.
            % =============================================================
            
            statePath = ...
                savedMatPath;
            
            app.LastCosmicStatePath = ...
                statePath;
            
            app.LastCosmicEvidenceFolder = ...
                evidenceFolder;
            
            app.updateCurrentManifestStage( ...
                'Cosmic', ...
                savedMatPath, ...
                statePath, ...
                evidenceFolder, ...
                'materialized_cube', ...
                savedMatPath);
            
        end
        
        
        function appendMasterLog(app, stepName, savedMatPath, statePath, evidenceFolder)
            
            app.initializeProjectPaths();
            
            logPath = fullfile(app.Paths.MetadataRoot, 'scanpretreat_master_log.csv');
            
            SessionMetadata = app.buildSessionMetadata();
            
            scanID = string(app.getCurrentScanID());
            
            if isempty(app.CandidateTable)
                totalCandidates = 0;
                acceptedCandidates = 0;
                correctedCandidates = 0;
                ignoredCandidates = 0;
                pendingCandidates = 0;
            else
                totalCandidates = height(app.CandidateTable);
                acceptedCandidates = nnz(app.CandidateTable.Accepted);
                correctedCandidates = nnz(app.CandidateTable.Corrected);
                ignoredCandidates = nnz(app.CandidateTable.Reviewed & ~app.CandidateTable.Accepted);
                pendingCandidates = nnz(~app.CandidateTable.Reviewed);
            end
            
            if isempty(app.CurrentROIMask)
                roiPixels = NaN;
            else
                roiPixels = nnz(app.CurrentROIMask);
            end
            
            if isempty(app.ArtifactMask2D)
                artifactPixels = NaN;
                validPixels = NaN;
            else
                artifactPixels = nnz(app.ArtifactMask2D);
                validPixels = nnz(app.ValidPixelMask2D);
            end
            
            if isfield(app.CorrectParams, 'Method')
                correctionMethod = string(char(app.CorrectParams.Method));
            else
                correctionMethod = "";
            end
            
            row = table( ...
                string(datestr(now,'yyyy-mm-dd HH:MM:SS')), ...
                string(SessionMetadata.SessionID), ...
                string(SessionMetadata.User), ...
                string(SessionMetadata.Role), ...
                string(SessionMetadata.Email), ...
                string(stepName), ...
                scanID, ...
                string(app.CurrentScanName), ...
                string(app.CurrentMatPath), ...
                string(savedMatPath), ...
                string(statePath), ...
                string(evidenceFolder), ...
                string(app.DataInputRoot), ...
                string(app.DataOutputRoot), ...
                string(app.ScanInputRoot), ...
                string(app.ScanOutputRoot), ...
                string(app.ProjectRoot), ...
                string(app.CosmicDetectionMode), ...
                string(app.CosmicWNMode), ...
                correctionMethod, ...
                totalCandidates, ...
                acceptedCandidates, ...
                correctedCandidates, ...
                ignoredCandidates, ...
                pendingCandidates, ...
                roiPixels, ...
                artifactPixels, ...
                validPixels, ...
                'VariableNames', { ...
                'DateTime', ...
                'SessionID', ...
                'User', ...
                'Role', ...
                'Email', ...
                'Step', ...
                'ScanID', ...
                'ScanName', ...
                'InputFile', ...
                'OutputFile', ...
                'StateFile', ...
                'EvidenceFolder', ...
                'DataInputRoot', ...
                'DataOutputRoot', ...
                'ScanInputRoot', ...
                'ScanOutputRoot', ...
                'ProjectRoot', ...
                'CosmicDetectionMode', ...
                'CosmicWNMode', ...
                'CorrectionMethod', ...
                'TotalCandidates', ...
                'AcceptedCandidates', ...
                'CorrectedCandidates', ...
                'IgnoredCandidates', ...
                'PendingCandidates', ...
                'ROIPixels', ...
                'ArtifactPixels', ...
                'ValidPixels'});
            
            if exist(logPath, 'file')
                old = readtable(logPath, 'TextType', 'string');
                
                missingOld = setdiff(row.Properties.VariableNames, old.Properties.VariableNames);
                for i = 1:numel(missingOld)
                    old.(missingOld{i}) = strings(height(old), 1);
                end
                
                missingNew = setdiff(old.Properties.VariableNames, row.Properties.VariableNames);
                for i = 1:numel(missingNew)
                    row.(missingNew{i}) = strings(height(row), 1);
                end
                
                row = row(:, old.Properties.VariableNames);
                out = [old; row];
            else
                out = row;
            end
            
            writetable(out, logPath);
        end
        
        function idx = getCandidateTreeDisplayIndices(app)
            
            idx = [];
            
            if isempty(app.CandidateTable) || ...
                    height(app.CandidateTable) == 0
                return
            end
            
            app.CandidateTable = ...
                app.ensureCosmicCandidateTableSchema( ...
                app.CandidateTable);
            
            % =============================================================
            % DISPLAY RULE
            %
            % While review is open:
            %   show every old and newly detected candidate.
            %
            % Only after final confirmation:
            %   show accepted candidates only.
            %
            % Do not use CosmicConfirmed here because ROI re-detection may add
            % new pending candidates after an earlier confirmation/correction.
            % =============================================================
            
            if app.CosmicReviewFinalized
                
                idx = ...
                    find(app.CandidateTable.Accepted);
                
            else
                
                idx = ...
                    (1:height(app.CandidateTable))';
            end
            
            idx = ...
                idx(:);
        end
        
        function plotConfirmedMapReview(app)
            
            if isempty(app.CubeOriginal)
                return
            end
            
            if isempty(app.ConfirmedMask)
                app.ConfirmedMask = app.buildMaskFromAcceptedCandidates();
            end
            
            [rawMap,rawLabel] = app.buildRamanMap(app.CubeOriginal);
            countMap = sum(app.ConfirmedMask,3);
            
            ax1 = app.UIAxes;
            app.resetImageAxis(ax1);
            app.showMapOnNormalAxes(ax1,rawMap);
            title(ax1,{app.CurrentScanName,['Raw map | ' rawLabel]},'Interpreter','none');
            
            hold(ax1,'on');
            if any(countMap(:))
                contour(ax1,countMap > 0,[0.5 0.5],'r','LineWidth',1.1);
            end
            if ~isempty(app.CurrentROIMask) && app.ROIReady
                contour(ax1,app.CurrentROIMask,[0.5 0.5],'y','LineWidth',1.0);
            end
            hold(ax1,'off');
            
            ax2 = app.UIAxes2;
            app.resetImageAxis(ax2);
            imagesc(ax2,countMap);
            axis(ax2,'image');
            ax2.YDir = 'normal';
            colormap(ax2,'hot');
            colorbar(ax2);
            title(ax2,'Confirmed cosmic candidate count map');
            box(ax2,'on');
            
            app.setText({ ...
                'CONFIRMED COSMIC MAP REVIEW'; ...
                '================================'; ...
                ''; ...
                ['Scan: ' app.CurrentScanName]; ...
                sprintf('Accepted candidates: %d',nnz(app.CandidateTable.Accepted)); ...
                sprintf('Confirmed cosmic voxels: %d',nnz(app.ConfirmedMask)); ...
                ''; ...
                'Axis 1: raw Raman map with confirmed-cosmic overlay.'; ...
                'Axis 2: confirmed cosmic count map.'; ...
                ''; ...
                'Note:'; ...
                'This is before/without correction if Apply for all has not been used yet.'});
        end
        
        function plotConfirmedRawSpectraReview(app)
            
            acceptedIdx = find(app.CandidateTable.Accepted(:))';
            
            if isempty(acceptedIdx)
                return
            end
            
            plotIdx = acceptedIdx;
            sampled = false;
            
            if numel(plotIdx) > app.MaxReviewPlotSpectra
                rng(1);
                plotIdx = plotIdx(randperm(numel(plotIdx),app.MaxReviewPlotSpectra));
                sampled = true;
            end
            
            cube = app.CubeOriginal;
            
            ax1 = app.UIAxes;
            app.resetSpectrumAxis(ax1);
            hold(ax1,'on');
            
            yAll = [];
            
            for i = plotIdx
                y = app.CandidateTable.Y(i);
                x = app.CandidateTable.X(i);
                spec = squeeze(cube(y,x,:));
                plot(ax1,app.Wavenumber,spec,'Color',[0.78 0.78 0.78],'LineWidth',0.35);
                yAll = [yAll; spec(:)]; %#ok<AGROW>
            end
            
            for i = plotIdx
                y = app.CandidateTable.Y(i);
                x = app.CandidateTable.X(i);
                k = app.CandidateTable.KPeak(i);
                spec = squeeze(cube(y,x,:));
                plot(ax1,app.Wavenumber(k),spec(k),'r.','MarkerSize',10);
            end
            
            hold(ax1,'off');
            title(ax1,sprintf('Raw spectra of confirmed cosmic candidates | shown=%d',numel(plotIdx)), ...
                'Interpreter','none');
            app.setNiceSpectralLimits(ax1,app.Wavenumber,yAll);
            
            ax2 = app.UIAxes2;
            app.resetImageAxis(ax2);
            
            if isempty(app.ConfirmedMask)
                app.ConfirmedMask = app.buildMaskFromAcceptedCandidates();
            end
            
            countMap = sum(app.ConfirmedMask,3);
            imagesc(ax2,countMap);
            axis(ax2,'image');
            ax2.YDir = 'normal';
            colormap(ax2,'hot');
            colorbar(ax2);
            title(ax2,'Confirmed candidate count map');
            box(ax2,'on');
            
            if sampled
                sampleText = sprintf('Only %d spectra are plotted for speed; all accepted candidates are stored.',numel(plotIdx));
            else
                sampleText = 'All accepted spectra are plotted.';
            end
            
            app.setText({ ...
                'CONFIRMED RAW SPECTRA REVIEW'; ...
                '================================'; ...
                ''; ...
                sprintf('Accepted candidates: %d',numel(acceptedIdx)); ...
                sprintf('Displayed spectra: %d',numel(plotIdx)); ...
                sampleText; ...
                ''; ...
                'Axis 1: raw spectra with detected spike markers.'; ...
                'Axis 2: confirmed candidate count map.'});
        end
        
        
        function plotCorrectedSpectraReview(app)
            
            if isempty(app.CorrectedCube)
                uialert(app.UIFigure,'No corrected cube is available. Press Apply for all first.','No corrected cube');
                return
            end
            
            acceptedIdx = find(app.CandidateTable.Accepted(:))';
            
            if isempty(acceptedIdx)
                return
            end
            
            plotIdx = acceptedIdx;
            sampled = false;
            
            if numel(plotIdx) > app.MaxReviewPlotSpectra
                rng(1);
                plotIdx = plotIdx(randperm(numel(plotIdx),app.MaxReviewPlotSpectra));
                sampled = true;
            end
            
            ax1 = app.UIAxes;
            app.resetSpectrumAxis(ax1);
            hold(ax1,'on');
            
            yAll1 = [];
            
            for i = plotIdx
                y = app.CandidateTable.Y(i);
                x = app.CandidateTable.X(i);
                spec = squeeze(app.CubeOriginal(y,x,:));
                plot(ax1,app.Wavenumber,spec,'Color',[0.75 0.75 0.75],'LineWidth',0.35);
                yAll1 = [yAll1; spec(:)]; %#ok<AGROW>
            end
            
            for i = plotIdx
                y = app.CandidateTable.Y(i);
                x = app.CandidateTable.X(i);
                k = app.CandidateTable.KPeak(i);
                spec = squeeze(app.CubeOriginal(y,x,:));
                plot(ax1,app.Wavenumber(k),spec(k),'r.','MarkerSize',9);
            end
            
            hold(ax1,'off');
            title(ax1,sprintf('Raw accepted spectra | shown=%d',numel(plotIdx)), ...
                'Interpreter','none');
            app.setNiceSpectralLimits(ax1,app.Wavenumber,yAll1);
            
            ax2 = app.UIAxes2;
            app.resetSpectrumAxis(ax2);
            hold(ax2,'on');
            
            yAll2 = [];
            
            for i = plotIdx
                y = app.CandidateTable.Y(i);
                x = app.CandidateTable.X(i);
                spec = squeeze(app.CorrectedCube(y,x,:));
                plot(ax2,app.Wavenumber,spec,'Color',[0.20 0.20 0.85],'LineWidth',0.35);
                yAll2 = [yAll2; spec(:)]; %#ok<AGROW>
            end
            
            hold(ax2,'off');
            title(ax2,sprintf('Corrected accepted spectra | shown=%d',numel(plotIdx)), ...
                'Interpreter','none');
            app.setNiceSpectralLimits(ax2,app.Wavenumber,yAll2);
            
            if sampled
                sampleText = sprintf('Only %d spectra are plotted for speed; all accepted candidates were corrected/stored.',numel(plotIdx));
            else
                sampleText = 'All accepted spectra are plotted.';
            end
            
            app.setText({ ...
                'RAW VS CORRECTED SPECTRA REVIEW'; ...
                '================================'; ...
                ''; ...
                sprintf('Accepted candidates: %d',numel(acceptedIdx)); ...
                sprintf('Corrected candidates: %d',nnz(app.CandidateTable.Corrected)); ...
                sprintf('Displayed spectra: %d',numel(plotIdx)); ...
                sampleText; ...
                ''; ...
                'Axis 1: raw accepted spectra with spike markers.'; ...
                'Axis 2: corrected accepted spectra.'});
        end
        
        function statePath = saveArtifactStateData( ...
                app, ...
                savedMatPath, ...
                evidenceFolder)
            
            if nargin < 3
                evidenceFolder = '';
            end
            
            if isempty(savedMatPath) || ...
                    ~isfile(savedMatPath)
                
                error( ...
                    'Artifact checkpoint does not exist.');
            end
            
            % No duplicated state MAT.
            statePath = ...
                savedMatPath;
            
            spectralDataPath = ...
                app.getCurrentSpectralDataPath();
            
            app.updateCurrentManifestStage( ...
                'Artifact', ...
                savedMatPath, ...
                statePath, ...
                evidenceFolder, ...
                'linked_spectral_cube', ...
                spectralDataPath);
            
        end
        
        
        function cmapName = getArtifactColormapName(app)
            
            if isempty(app.ColorsDropDown.Value)
                cmapName = 'hot';
                return
            end
            
            cmapName = lower(char(app.ColorsDropDown.Value));
            
            validNames = {'turbo','parula','gray','hot','jet','bone','copper','pink'};
            
            if ~ismember(cmapName,validNames)
                cmapName = 'hot';
            end
        end
        
        
        function applyArtifactColormap(app,ax)
            
            cmapName = app.getArtifactColormapName();
            
            try
                colormap(ax,cmapName);
            catch
                colormap(ax,'hot');
            end
        end
        
        function showArtifactMapOnAxes(app, ax, img)
            
            app.resetImageAxis(ax);
            
            img = ...
                double(img);
            
            imagesc( ...
                ax, ...
                img);
            
            axis( ...
                ax, ...
                'image');
            
            % Row 1 remains at the top.
            ax.YDir = ...
                'reverse';
            
            app.applyArtifactColormap(ax);
            
            colorbar(ax);
            
            vals = ...
                img(isfinite(img));
            
            if ~isempty(vals)
                
                lo = ...
                    prctile(vals, 2);
                
                hi = ...
                    prctile(vals, 98);
                
                if isfinite(lo) && ...
                        isfinite(hi) && ...
                        lo < hi
                    
                    caxis( ...
                        ax, ...
                        [lo hi]);
                end
            end
            
            ax.XLim = ...
                [0.5, size(img, 2) + 0.5];
            
            ax.YLim = ...
                [0.5, size(img, 1) + 0.5];
            
            grid(ax, 'off');
            box(ax, 'on');
            
            ax.Layer = ...
                'top';
        end
        
        function plotArtifactMaskedBlackAxis(app,ax,rawMap,artifactMask,titleText)
            
            app.resetImageAxis(ax);
            
            app.showArtifactMapOnAxes(ax,rawMap);
            
            hold(ax,'on');
            
            blackOverlay = zeros([size(rawMap) 3]);
            
            h = imagesc(ax,blackOverlay);
            set(h,'AlphaData',double(artifactMask));
            
            hold(ax,'off');
            
            title(ax,titleText,'Interpreter','none');
        end
        
        function payload = buildHomePretreatPayload(app,stageName,savedMatPath,statePath,evidenceFolder)
            
            if nargin < 5
                evidenceFolder = '';
            end
            
            stageName = char(stageName);
            
            payload = struct();
            
            % ===== lightweight linking payload =====
            payload.SourceApp = 'scanpretreat';
            payload.Stage = stageName;
            payload.ScanName = app.CurrentScanName;
            payload.ScanID = app.getCurrentScanID();
            
            payload.ProjectRoot = app.ProjectRoot;
            payload.OriginalMatPath = app.CurrentMatPath;
            payload.SavedMatPath = savedMatPath;
            payload.StatePath = statePath;
            payload.EvidenceFolder = evidenceFolder;
            
            payload.CreatedDate = datestr(now,'yyyy-mm-dd HH:MM:SS');
            
            % Do NOT return Cube / RamanData / masks / maps to Home.
            % Other apps should load the data from SavedMatPath.
            payload.PayloadMode = 'lightweight_path_link';
            payload.HeavyDataIncluded = false;
            
            if ~isempty(app.Wavenumber)
                payload.WavenumberCount = numel(app.Wavenumber);
                payload.WavenumberMin = min(app.Wavenumber);
                payload.WavenumberMax = max(app.Wavenumber);
            else
                payload.WavenumberCount = 0;
                payload.WavenumberMin = NaN;
                payload.WavenumberMax = NaN;
            end
            
            if ~isempty(app.CubeOriginal)
                payload.CubeSize = size(app.CubeOriginal);
            else
                payload.CubeSize = [];
            end
            
            % ===== cosmic summary =====
            if isempty(app.CandidateTable)
                payload.TotalCandidates = 0;
                payload.AcceptedCandidates = 0;
                payload.CorrectedCandidates = 0;
                payload.IgnoredCandidates = 0;
                payload.PendingCandidates = 0;
            else
                payload.TotalCandidates = height(app.CandidateTable);
                payload.AcceptedCandidates = nnz(app.CandidateTable.Accepted);
                payload.CorrectedCandidates = nnz(app.CandidateTable.Corrected);
                payload.IgnoredCandidates = nnz(app.CandidateTable.Reviewed & ~app.CandidateTable.Accepted);
                payload.PendingCandidates = nnz(~app.CandidateTable.Reviewed);
            end
            
            payload.CosmicConfirmed = app.CosmicConfirmed;
            payload.CorrectionReady = app.CorrectionReady;
            payload.CosmicDetectionMode = app.CosmicDetectionMode;
            payload.CosmicWNMode = app.CosmicWNMode;
            
            if isfield(app.CorrectParams,'Method')
                payload.CosmicCorrectionMethod = char(app.CorrectParams.Method);
            else
                payload.CosmicCorrectionMethod = '';
            end
            
            % ===== artifact summary =====
            payload.ArtifactReady = app.ArtifactReady;
            payload.ArtifactConfirmed = app.ArtifactConfirmed;
            
            if isempty(app.ArtifactMask2D)
                payload.ArtifactPixels = NaN;
                payload.ValidPixels = NaN;
                payload.ArtifactComponents = NaN;
            else
                payload.ArtifactPixels = nnz(app.ArtifactMask2D);
                payload.ValidPixels = nnz(app.ValidPixelMask2D);
                payload.ArtifactComponents = height(app.ArtifactTable);
            end
            
            if isprop(app,'ArtifactViewMode')
                payload.ArtifactViewMode = app.ArtifactViewMode;
            else
                payload.ArtifactViewMode = '';
            end
            
            if isprop(app,'ArtifactColorMap')
                payload.ArtifactColorMap = app.ArtifactColorMap;
            else
                payload.ArtifactColorMap = '';
            end
        end
        
        
        function returnToHomeApp(app)
            
            if isempty(app.mainApp) || ~isvalid(app.mainApp)
                uialert(app.UIFigure, ...
                    'Home app is not available. The result was saved, but the app cannot return to Home automatically.', ...
                    'Home not available');
                return
            end
            
            try
                app.mainApp.UIFigure.Visible = 'on';
                
                try
                    app.mainApp.UIFigure.WindowState = 'normal';
                catch
                end
                
                try
                    figure(app.mainApp.UIFigure);
                catch
                end
                
                if ismethod(app.mainApp,'addHistory')
                    app.mainApp.addHistory( ...
                        'Returned to scanspectra from scanpretreat.');
                end
                
                % Keep Pretreat alive but hide it, so user can reopen without losing app object.
                app.UIFigure.Visible = 'off';
                
            catch ME
                uialert(app.UIFigure,ME.message,'Return to Home error');
            end
        end
        
        
        function M = buildSessionMetadata(app)
            
            M = struct();
            
            M.User = '';
            M.Email = '';
            M.Role = '';
            M.SessionID = '';
            M.SessionLogPath = '';
            M.MainhomeLoginTime = '';
            
            if ~isempty(app.SessionInfo) && isstruct(app.SessionInfo)
                
                if isfield(app.SessionInfo, 'User')
                    M.User = char(app.SessionInfo.User);
                end
                
                if isfield(app.SessionInfo, 'Email')
                    M.Email = char(app.SessionInfo.Email);
                end
                
                if isfield(app.SessionInfo, 'Role')
                    M.Role = char(app.SessionInfo.Role);
                end
                
                if isfield(app.SessionInfo, 'SessionID')
                    M.SessionID = char(app.SessionInfo.SessionID);
                end
                
                if isfield(app.SessionInfo, 'SessionLogPath')
                    M.SessionLogPath = char(app.SessionInfo.SessionLogPath);
                end
                
                if isfield(app.SessionInfo, 'MainhomeLoginTime')
                    M.MainhomeLoginTime = char(app.SessionInfo.MainhomeLoginTime);
                end
            end
            
            M.DataInputRoot = app.DataInputRoot;
            M.DataOutputRoot = app.DataOutputRoot;
            M.ScanInputRoot = app.ScanInputRoot;
            M.ScanOutputRoot = app.ScanOutputRoot;
            M.ProjectRoot = app.ProjectRoot;
        end
        
        function ok = sendActiveLoadedScanToMainApp(app, sourceAction)
            
            ok = false;
            
            % =============================================================
            % 1. SOURCE LABEL
            % =============================================================
            
            if nargin < 2 || isempty(sourceAction)
                
                sourceAction = ...
                    'Active scan update';
            end
            
            sourceAction = ...
                char(string(sourceAction));
            
            % =============================================================
            % 2. VALIDATE PARENT SCANSPECTRA
            % =============================================================
            
            if isempty(app.mainApp)
                
                warning( ...
                    'RamanCube:NoScanSpectraParent', ...
                    'scanpretreat has no scanspectra parent app.');
                
                return
            end
            
            try
                
                parentValid = ...
                    isvalid(app.mainApp);
                
            catch
                
                parentValid = ...
                    false;
            end
            
            if ~parentValid
                
                warning( ...
                    'RamanCube:InvalidScanSpectraParent', ...
                    'The scanspectra parent app is no longer valid.');
                
                return
            end
            
            if ~ismethod( ...
                    app.mainApp, ...
                    'receiveLoadedScanFromPretreat')
                
                warning( ...
                    'RamanCube:MissingPretreatReceiver', ...
                    ['The scanspectra parent does not implement ' ...
                    'receiveLoadedScanFromPretreat().']);
                
                return
            end
            
            % =============================================================
            % 3. VALIDATE CURRENT SCAN
            % =============================================================
            
            if isempty(app.CubeOriginal)
                
                warning( ...
                    'RamanCube:NoPretreatCube', ...
                    ['No CubeOriginal is available after: ' ...
                    sourceAction]);
                
                return
            end
            
            if isempty(app.Wavenumber)
                
                warning( ...
                    'RamanCube:NoPretreatWavenumber', ...
                    ['No wavenumber axis is available after: ' ...
                    sourceAction]);
                
                return
            end
            
            if isempty(app.CurrentScanName)
                
                warning( ...
                    'RamanCube:NoPretreatScanName', ...
                    ['No CurrentScanName is available after: ' ...
                    sourceAction]);
                
                return
            end
            
            % =============================================================
            % 4. GET COMMITTED PROCESSING CUBE
            %
            % IMPORTANT:
            % getCommittedCube() never returns TrialCorrectedCube.
            %
            % Priority:
            %   CorrectedCube after cosmic correction
            %   otherwise CubeOriginal
            %
            % Artifact removal does not alter the spectral cube.
            % Artifact pixels are excluded using ValidPixelMask2D.
            % =============================================================
            
            activeCube = ...
                app.getCommittedCube();
            
            if isempty(activeCube) || ...
                    ndims(activeCube) ~= 3
                
                warning( ...
                    'RamanCube:InvalidCommittedCube', ...
                    ['The committed Raman cube is invalid after: ' ...
                    sourceAction]);
                
                return
            end
            
            activeCube = ...
                double(activeCube);
            
            [ny, nx, nWn] = ...
                size(activeCube);
            
            % =============================================================
            % VALIDATE ORIGINAL RAW CUBE
            %
            % The raw cube is required so single_extract can later export
            % both raw and pretreated spectra for every selected cluster pixel.
            % =============================================================
            
            rawCube = ...
                double(app.CubeOriginal);
            
            if isempty(rawCube) || ...
                    ndims(rawCube) ~= 3
                
                warning( ...
                    'RamanCube:InvalidOriginalRawCube', ...
                    ['CubeOriginal is unavailable or invalid after: ' ...
                    sourceAction]);
                
                return
            end
            
            if ~isequal(size(rawCube), size(activeCube))
                
                warning( ...
                    'RamanCube:RawPretreatedCubeSizeMismatch', ...
                    ['CubeOriginal and the committed cube have different sizes.' ...
                    newline ...
                    'Raw cube: %s' ...
                    newline ...
                    'Committed cube: %s'], ...
                    mat2str(size(rawCube)), ...
                    mat2str(size(activeCube)));
                
                return
            end
            
            wn = ...
                double(app.Wavenumber(:));
            
            if numel(wn) ~= nWn
                
                warning( ...
                    'RamanCube:PretreatWavenumberMismatch', ...
                    ['Wavenumber length does not match the committed cube ' ...
                    'after: ' sourceAction]);
                
                return
            end
            
            % =============================================================
            % 5. BUILD FINITE MASK
            % =============================================================
            
            finiteMask = ...
                all(isfinite(activeCube), 3);
            
            % =============================================================
            % 6. BUILD ARTIFACT MASK
            % =============================================================
            
            if ~isempty(app.ArtifactMask2D) && ...
                    isequal(size(app.ArtifactMask2D), [ny nx])
                
                artifactMask = ...
                    logical(app.ArtifactMask2D);
                
            else
                
                artifactMask = ...
                    false(ny, nx);
            end
            
            % =============================================================
            % 7. BUILD VALID MASK
            %
            % Priority:
            %   Artifact-valid mask
            %   ROI mask
            %   finite spectra
            % =============================================================
            
            validMask = ...
                finiteMask;
            
            validMaskSource = ...
                'Finite spectra';
            
            if ~isempty(app.ValidPixelMask2D) && ...
                    isequal(size(app.ValidPixelMask2D), [ny nx])
                
                validMask = ...
                    validMask & ...
                    logical(app.ValidPixelMask2D);
                
                validMaskSource = ...
                    'ValidPixelMask2D after Artifact removal';
                
            elseif app.ROIReady && ...
                    ~isempty(app.CurrentROIMask) && ...
                    isequal(size(app.CurrentROIMask), [ny nx])
                
                validMask = ...
                    validMask & ...
                    logical(app.CurrentROIMask);
                
                validMaskSource = ...
                    'CurrentROIMask';
                
            else
                
                validMask = ...
                    validMask & ...
                    ~artifactMask;
                
                validMaskSource = ...
                    'Finite spectra excluding ArtifactMask2D';
            end
            
            % Always enforce the artifact exclusion.
            validMask = ...
                validMask & ...
                ~artifactMask;
            
            if ~any(validMask(:))
                
                warning( ...
                    'RamanCube:NoValidPretreatPixels', ...
                    ['No valid pixels remain after: ' ...
                    sourceAction]);
                
                return
            end
            
            % =============================================================
            % 8. DETERMINE CURRENT STAGE
            % =============================================================
            
            if app.ArtifactReady && ...
                    ~isempty(app.ValidPixelMask2D)
                
                stageName = ...
                    'Cosmic-corrected and artifact-masked scan';
                
                sourceStage = ...
                    'Artifact';
                
            elseif ~isempty(app.CorrectedCube)
                
                stageName = ...
                    'Cosmic-corrected scan';
                
                sourceStage = ...
                    'Cosmic';
                
            else
                
                stageName = ...
                    'Raw loaded scan';
                
                sourceStage = ...
                    'Original';
            end
            
            % =============================================================
            % 9. SOURCE MAT PATH
            % =============================================================
            
            sourceMatPath = ...
                app.CurrentMatPath;
            
            if strcmp(sourceStage, 'Artifact') && ...
                    isfield(app.LastSavedPayload, 'SavedMatPath') && ...
                    ~isempty(app.LastSavedPayload.SavedMatPath)
                
                sourceMatPath = ...
                    char(string(app.LastSavedPayload.SavedMatPath));
                
            elseif strcmp(sourceStage, 'Cosmic') && ...
                    isfield(app.LastSavedPayload, 'SavedMatPath') && ...
                    ~isempty(app.LastSavedPayload.SavedMatPath)
                
                sourceMatPath = ...
                    char(string(app.LastSavedPayload.SavedMatPath));
            end
            
            % =============================================================
            % 10. BUILD HEAVY IN-MEMORY PAYLOAD
            %
            % This payload is sent to scanspectra and may later be passed to
            % single_extract.
            %
            % Cube:
            %   committed processing cube used for segmentation.
            %
            % RawCube:
            %   original acquisition cube before cosmic correction.
            %
            % Artifact pixels remain in both cubes at their original spatial
            % positions. They are excluded analytically using the masks.
            % =============================================================
            
            payload = ...
                struct();
            
            % -------------------------------------------------------------
            % SOURCE AND WORKFLOW IDENTITY
            % -------------------------------------------------------------
            
            payload.SourceApp = ...
                'scanpretreat';
            
            payload.SourceAction = ...
                sourceAction;
            
            payload.SourceStage = ...
                sourceStage;
            
            payload.Stage = ...
                stageName;
            
            payload.ScanName = ...
                string(app.CurrentScanName);
            
            payload.ScanID = ...
                string(app.getCurrentScanID());
            
            payload.SourceMatPath = ...
                string(sourceMatPath);
            
            payload.OriginalSourceMatPath = ...
                string(app.CurrentMatPath);
            
            payload.OutputRoot = ...
                string(app.ScanOutputRoot);
            
            % -------------------------------------------------------------
            % COMPLETE RAMAN DATA
            % -------------------------------------------------------------
            
            payload.Cube = ...
                double(activeCube);
            
            payload.RawCube = ...
                double(rawCube);
            
            payload.Wavenumber = ...
                double(wn(:));
            
            payload.CubeSize = ...
                [ny nx nWn];
            
            payload.RawCubeSize = ...
                size(rawCube);
            
            % -------------------------------------------------------------
            % DATA DEFINITIONS
            % -------------------------------------------------------------
            
            payload.RawCubeDefinition = ...
                ['Original Raman cube before cosmic correction and ' ...
                'artifact masking.'];
            
            payload.PretreatedCubeDefinition = ...
                ['Committed Raman cube used for PCA/K-means segmentation. ' ...
                'It contains committed cosmic correction when available.'];
            
            payload.ArtifactHandling = ...
                ['Artifact removal does not replace analytical spectra. ' ...
                'Artifact pixels are excluded through ValidMask2D and ' ...
                'ArtifactMask2D. Display-only inpainting is not transferred.'];
            
            payload.RawAndPretreatedPixelAlignment = ...
                ['RawCube and Cube use identical dimensions and indexing. ' ...
                'RawCube(y,x,k) and Cube(y,x,k) refer to the same acquired ' ...
                'pixel and wavenumber channel.'];
            
            payload.SpectraOrientation = ...
                ['Cube dimensions are row x column x wavenumber. ' ...
                'Cluster export later uses rows = pixels and columns = ' ...
                'wavenumber channels.'];
            
            % -------------------------------------------------------------
            % VALID AND ARTIFACT MASKS
            % -------------------------------------------------------------
            
            payload.ValidMask = ...
                logical(validMask);
            
            payload.ValidMask2D = ...
                logical(validMask);
            
            payload.ValidMaskSource = ...
                validMaskSource;
            
            payload.ArtifactMask2D = ...
                logical(artifactMask);
            
            payload.ValidPixelCount = ...
                nnz(validMask);
            
            payload.ArtifactPixelCount = ...
                nnz(artifactMask);
            
            payload.TotalPixelCount = ...
                numel(validMask);
            
            % -------------------------------------------------------------
            % ROI
            % -------------------------------------------------------------
            
            payload.ROIReady = ...
                logical(app.ROIReady);
            
            if app.ROIReady && ...
                    ~isempty(app.CurrentROIMask) && ...
                    isequal(size(app.CurrentROIMask), [ny nx])
                
                payload.ROIMask = ...
                    logical(app.CurrentROIMask);
                
            else
                
                payload.ROIMask = ...
                    true(ny, nx);
            end
            
            % -------------------------------------------------------------
            % COSMIC METADATA
            % -------------------------------------------------------------
            
            payload.CosmicConfirmed = ...
                logical(app.CosmicConfirmed);
            
            payload.CorrectionReady = ...
                logical(app.CorrectionReady);
            
            payload.CosmicDetectionMode = ...
                app.CosmicDetectionMode;
            
            payload.CosmicCorrectionParameterMode = ...
                app.CosmicCorrectionParameterMode;
            
            payload.CosmicWNMode = ...
                app.CosmicWNMode;
            
            payload.CosmicWNIncludeRanges = ...
                app.CosmicWNIncludeRanges;
            
            payload.CosmicWNExcludeRanges = ...
                app.CosmicWNExcludeRanges;
            
            payload.CosmicCandidateCount = ...
                height(app.CandidateTable);
            
            if isempty(app.CandidateTable)
                
                payload.CosmicAcceptedCount = ...
                    0;
                
                payload.CosmicCorrectedCount = ...
                    0;
                
            else
                
                payload.CosmicAcceptedCount = ...
                    nnz(app.CandidateTable.Accepted);
                
                payload.CosmicCorrectedCount = ...
                    nnz(app.CandidateTable.Corrected);
            end
            
            payload.AuxiliaryCosmicEnabled = ...
                logical(app.AuxCosmicEnabled);
            
            payload.AuxiliaryCosmicRange = ...
                double(app.AuxCosmicRange);
            
            payload.AuxiliaryCosmicCorrectedCount = ...
                double(app.AuxCosmicCorrectedCount);
            
            % -------------------------------------------------------------
            % ARTIFACT METADATA
            % -------------------------------------------------------------
            
            payload.ArtifactReady = ...
                logical(app.ArtifactReady);
            
            payload.ArtifactConfirmed = ...
                logical(app.ArtifactConfirmed);
            
            payload.ArtifactModeName = ...
                app.ArtifactModeName;
            
            payload.ArtifactComponentCount = ...
                height(app.ArtifactTable);
            
            payload.ArtifactViewMode = ...
                app.ArtifactViewMode;
            
            payload.DisplayInpaintingTransferred = ...
                false;
            
            % -------------------------------------------------------------
            % SPECTRAL ACQUISITION METADATA
            % -------------------------------------------------------------
            
            payload.SpectralSampling = ...
                app.SpectralSampling;
            
            payload.SpectralResolution = ...
                app.SpectralResolution;
            
            payload.SpectralResolutionSource = ...
                app.SpectralResolutionSource;
            
            % -------------------------------------------------------------
            % SESSION AND TRANSFER METADATA
            % -------------------------------------------------------------
            
            payload.LoadedAt = ...
                datestr(now, 'yyyy-mm-dd HH:MM:SS');
            
            payload.CreatedAt = ...
                datetime('now');
            
            payload.MATLABVersion = ...
                version;
            
            payload.PayloadMode = ...
                'active_in_memory_scan';
            
            payload.HeavyDataIncluded = ...
                true;
            
            payload.RawCubeIncluded = ...
                true;
            
            payload.PretreatedCubeIncluded = ...
                true;
            
            % =============================================================
            % 11. SEND TO SCANSPECTRA
            % =============================================================
            
            try
                
                app.mainApp.receiveLoadedScanFromPretreat( ...
                    payload);
                
                ok = ...
                    true;
                
            catch ME
                
                warning( ...
                    'RamanCube:PretreatSendFailed', ...
                    'Failed to send active scan to scanspectra: %s', ...
                    ME.message);
                
                ok = ...
                    false;
            end
            
        end
        
        
        function initializeBackgroundTab(app)
            
            % =========================================================
            % DROP-DOWN CONTENT
            % =========================================================
            
            app.MorphologyDropDown.Items = { ...
                'Automatic / mixed', ...
                'Large isolated cells', ...
                'Dense small-cell population'};
            
            app.MorphologyDropDown.Value = ...
                'Automatic / mixed';
            
            app.SegmethodDropDown.Items = { ...
                'PCA + K-means', ...
                'Intensity threshold', ...
                'Manual only'};
            
            app.SegmethodDropDown.Value = ...
                'PCA + K-means';
            
            app.DropDown.Items = { ...
                'Initial clusters', ...
                'Assigned classes', ...
                'Confidence map', ...
                'Cell mask', ...
                'Background mask', ...
                'Uncertain mask', ...
                'Unassigned mask'};
            
            app.DropDown.Value = ...
                'Initial clusters';
            
            app.DropDown_2.Items = { ...
                'Add to cell', ...
                'Add to background', ...
                'Set uncertain', ...
                'Remove manual assignment'};
            
            app.DropDown_2.Value = ...
                'Add to cell';
            
            % =========================================================
            % BUTTON TEXT
            % =========================================================
            
            app.CheckandrunButton.Text = ...
                'Run segmentation';
            
            app.DisplayButton.Text = ...
                'Display';
            
            app.ManualeditButton.Text = ...
                'Manual edit';
            
            app.AssignButton.Text = ...
                'Assign';
            
            app.SplitButton_3.Text = ...
                'Split';
            
            app.MergeButton.Text = ...
                'Merge';
            
            app.RestoreButton.Text = ...
                'Restore';
            
            app.ValidateresultsButton.Text = ...
                'Validate results';
            
            % =========================================================
            % INITIAL STATE
            % =========================================================
            
            app.resetBackgroundState();
            
            % =========================================================
            % INITIAL TREE
            % =========================================================
            
            app.populateBackgroundTree();
            
            % =========================================================
            % INITIAL AXES
            % =========================================================
            
            app.resetImageAxis(app.UIAxes5);
            title(app.UIAxes5, ...
                'Background removal spatial map');
            
            app.resetSpectrumAxis(app.UIAxes6);
            title(app.UIAxes6, ...
                'Selected cluster spectrum');
            
            % =========================================================
            % INITIAL INFORMATION
            % =========================================================
            
            app.TextArea_3.Value = { ...
                'BACKGROUND REMOVAL'; ...
                '================================'; ...
                ''; ...
                'Waiting for confirmed Artifact removal result.'};
            
            app.TextArea_4.Value = { ...
                'QC PARAMETERS'; ...
                '================================'; ...
                ''; ...
                'No segmentation result is available.'};
            
            % Disable actions until data arrives.
            app.setBackgroundControlsEnabled(false);
        end
        
        
        function resetBackgroundState(app)
            
            app.BackgroundReady = false;
            app.BackgroundValidated = false;
            app.BackgroundConfirmed = false;
            
            app.BackgroundInputCube = [];
            app.BackgroundValidMask2D = logical.empty;
            app.BackgroundArtifactMask2D = logical.empty;
            
            app.BackgroundPreviewMap = [];
            app.BackgroundPreviewLabel = '';
            
            app.BackgroundLabelMap = [];
            app.BackgroundConfidenceMap = [];
            
            app.BackgroundClusters = struct( ...
                'ID', {}, ...
                'Name', {}, ...
                'ParentID', {}, ...
                'PixelIdx', {}, ...
                'Class', {}, ...
                'Active', {}, ...
                'MeanSpectrum', {}, ...
                'StdSpectrum', {}, ...
                'Metrics', {})
            
            app.BackgroundNextClusterID = 1;
            app.BackgroundSelectedClusterIDs = [];
            
            app.BackgroundCellMask2D = logical.empty;
            app.BackgroundBackgroundMask2D = logical.empty;
            app.BackgroundUncertainMask2D = logical.empty;
            app.BackgroundUnassignedMask2D = logical.empty;
            
            app.BackgroundManualClassMap = uint8.empty;
            
            app.BackgroundNumPCUsed = 0;
            app.BackgroundExplained = [];
            app.BackgroundPCAcoeff = [];
            app.BackgroundPCAmu = [];
            
            app.BackgroundUndoStack = {};
            app.BackgroundRedoStack = {};
            
            app.BackgroundLastSavedPath = '';
        end
        
        
        function setBackgroundControlsEnabled(app, tf)
            
            if tf
                state = 'on';
            else
                state = 'off';
            end
            
            app.MorphologyDropDown.Enable = state;
            app.SegmethodDropDown.Enable = state;
            app.CheckandrunButton.Enable = state;
            app.DisplayButton.Enable = state;
            app.DropDown.Enable = state;
            app.ManualeditButton.Enable = state;
            app.DropDown_2.Enable = state;
            
            app.AssignButton.Enable = state;
            app.SplitButton_3.Enable = state;
            app.MergeButton.Enable = state;
            app.RestoreButton.Enable = state;
            app.UndoButton.Enable = state;
            app.RedoButton.Enable = state;
            
            app.ReviewmasksButton.Enable = state;
            app.SpectralcompareButton.Enable = state;
            app.ValidateresultsButton.Enable = state;
            
            app.SaveimageButton.Enable = state;
            app.FinishButton_3.Enable = state;
        end
        
        
        function prepareBackgroundFromArtifact(app)
            
            % =========================================================
            % 1. VALIDATE ARTIFACT RESULT
            % =========================================================
            
            if isempty(app.CubeOriginal)
                
                error( ...
                    'No Raman cube is available for Background removal.');
            end
            
            if ~app.ArtifactReady || ...
                    isempty(app.ArtifactMask2D) || ...
                    isempty(app.ValidPixelMask2D)
                
                error([ ...
                    'Artifact removal has not produced a valid ' ...
                    'ArtifactMask2D and ValidPixelMask2D.']);
            end
            
            cube = double(app.getActiveCube());
            
            if ndims(cube) ~= 3
                
                error( ...
                    'The active Raman cube must be a 3-D array.');
            end
            
            [ny, nx, nWn] = size(cube);
            
            if numel(app.Wavenumber) ~= nWn
                
                error([ ...
                    'Wavenumber length does not match the active ' ...
                    'Raman cube.']);
            end
            
            if ~isequal(size(app.ValidPixelMask2D), [ny nx])
                
                error([ ...
                    'ValidPixelMask2D does not match the current ' ...
                    'scan dimensions.']);
            end
            
            if ~isequal(size(app.ArtifactMask2D), [ny nx])
                
                error([ ...
                    'ArtifactMask2D does not match the current ' ...
                    'scan dimensions.']);
            end
            
            % =========================================================
            % 2. STORE ARTIFACT OUTPUT AS BACKGROUND INPUT
            %
            % IMPORTANT:
            % Use active spectral cube.
            % Do not use ArtifactDisplayInpaintCube for analysis.
            % =========================================================
            
            app.BackgroundInputCube = cube;
            
            finiteMask = ...
                all(isfinite(cube), 3);
            
            app.BackgroundValidMask2D = ...
                logical(app.ValidPixelMask2D) & finiteMask;
            
            app.BackgroundArtifactMask2D = ...
                logical(app.ArtifactMask2D);
            
            if ~any(app.BackgroundValidMask2D(:))
                
                error( ...
                    'No valid pixels remain for Background removal.');
            end
            
            % =========================================================
            % 3. RESET OLD BACKGROUND RESULT
            % =========================================================
            
            app.BackgroundReady = true;
            app.BackgroundValidated = false;
            app.BackgroundConfirmed = false;
            
            app.BackgroundLabelMap = zeros(ny, nx);
            app.BackgroundConfidenceMap = nan(ny, nx);
            
            app.BackgroundClusters = struct( ...
                'ID', {}, ...
                'Name', {}, ...
                'ParentID', {}, ...
                'PixelIdx', {}, ...
                'Class', {}, ...
                'Active', {}, ...
                'MeanSpectrum', {}, ...
                'StdSpectrum', {}, ...
                'Metrics', {})
            
            app.BackgroundNextClusterID = 1;
            app.BackgroundSelectedClusterIDs = [];
            
            app.BackgroundManualClassMap = ...
                zeros(ny, nx, 'uint8');
            
            app.BackgroundCellMask2D = ...
                false(ny, nx);
            
            app.BackgroundBackgroundMask2D = ...
                false(ny, nx);
            
            app.BackgroundUncertainMask2D = ...
                false(ny, nx);
            
            app.BackgroundUnassignedMask2D = ...
                app.BackgroundValidMask2D;
            
            app.BackgroundUndoStack = {};
            app.BackgroundRedoStack = {};
            
            % =========================================================
            % 4. BUILD PREVIEW MAP
            % =========================================================
            
            [mapImg, mapLabel] = ...
                app.buildBackgroundPreviewMap();
            
            app.BackgroundPreviewMap = mapImg;
            app.BackgroundPreviewLabel = mapLabel;
            
            % =========================================================
            % 5. DISPLAY INPUT
            % =========================================================
            
            app.plotBackgroundInputView();
            app.populateBackgroundTree();
            app.updateBackgroundInfo();
            app.updateBackgroundQC();
            
            app.setBackgroundControlsEnabled(true);
            
            % =========================================================
            % 6. OPEN BACKGROUND TAB
            % =========================================================
            
            app.TabGroup.SelectedTab = ...
                app.BackgroundremovalTab;
            
            drawnow;
        end
        
        
        function [mapImg, mapLabel] = buildBackgroundPreviewMap(app)
            
            cube = app.BackgroundInputCube;
            wn = double(app.Wavenumber(:));
            
            [ny, nx, ~] = size(cube);
            
            % Prefer CH stretching region for biological material.
            useBand = ...
                wn >= 2840 & wn <= 2940;
            
            if nnz(useBand) >= 3
                
                mapImg = ...
                    mean(cube(:, :, useBand), 3, 'omitnan');
                
                mapLabel = ...
                    'Mean intensity 2840-2940 cm^{-1}';
                
            else
                
                useBand = ...
                    wn >= 600 & wn <= 1800;
                
                if nnz(useBand) >= 3
                    
                    mapImg = ...
                        mean(cube(:, :, useBand), 3, 'omitnan');
                    
                    mapLabel = ...
                        'Mean fingerprint intensity 600-1800 cm^{-1}';
                    
                else
                    
                    mapImg = ...
                        mean(cube, 3, 'omitnan');
                    
                    mapLabel = ...
                        'Total mean spectral intensity';
                end
            end
            
            mapImg = double(mapImg);
            
            validValues = ...
                mapImg(app.BackgroundValidMask2D);
            
            if isempty(validValues)
                
                mapImg = zeros(ny, nx);
                return
            end
            
            fillVal = ...
                median(validValues, 'omitnan');
            
            mapImg(~isfinite(mapImg)) = fillVal;
            
            % Artifact pixels are not valid for analysis.
            mapImg(~app.BackgroundValidMask2D) = NaN;
        end
        
        
        function plotBackgroundInputView(app)
            
            ax1 = app.UIAxes5;
            
            app.resetImageAxis(ax1);
            
            imagesc(ax1, app.BackgroundPreviewMap);
            
            axis(ax1, 'image');
            ax1.YDir = 'normal';
            
            colormap(ax1, 'hot');
            colorbar(ax1);
            
            vals = ...
                app.BackgroundPreviewMap( ...
                app.BackgroundValidMask2D & ...
                isfinite(app.BackgroundPreviewMap));
            
            if ~isempty(vals)
                
                lo = prctile(vals, 2);
                hi = prctile(vals, 98);
                
                if lo < hi
                    caxis(ax1, [lo hi]);
                end
            end
            
            hold(ax1, 'on');
            
            if any(app.BackgroundArtifactMask2D(:))
                
                contour( ...
                    ax1, ...
                    app.BackgroundArtifactMask2D, ...
                    [0.5 0.5], ...
                    'r', ...
                    'LineWidth', 1.0);
            end
            
            hold(ax1, 'off');
            
            title( ...
                ax1, ...
                {app.CurrentScanName; ...
                app.BackgroundPreviewLabel; ...
                'Red contour = excluded artifact'}, ...
                'Interpreter', 'none');
            
            ax2 = app.UIAxes6;
            
            app.resetSpectrumAxis(ax2);
            
            validIdx = ...
                find(app.BackgroundValidMask2D);
            
            [ny, nx, nWn] = ...
                size(app.BackgroundInputCube);
            
            X = ...
                reshape( ...
                app.BackgroundInputCube, ...
                ny * nx, ...
                nWn);
            
            if numel(validIdx) > 5000
                
                rng(1);
                
                validIdx = ...
                    validIdx( ...
                    randperm( ...
                    numel(validIdx), ...
                    5000));
            end
            
            meanSpec = ...
                mean(X(validIdx, :), 1, 'omitnan');
            
            plot( ...
                ax2, ...
                app.Wavenumber, ...
                meanSpec, ...
                'LineWidth', 1.5);
            
            title( ...
                ax2, ...
                'Mean spectrum of valid non-artifact pixels');
            
            app.setNiceSpectralLimits( ...
                ax2, ...
                app.Wavenumber, ...
                meanSpec);
        end
        
        

        function runBackgroundPCAKMeans(app)
            
            % =========================================================
            % 1. ASK PARAMETERS
            % =========================================================
            
            prompts = { ...
                'Initial number of clusters K:', ...
                'Explained variance target (%):', ...
                'Maximum number of PCs:', ...
                'K-means replicates:', ...
                'Random seed:'};
            
            defaults = { ...
                num2str(app.BackgroundK), ...
                num2str(app.BackgroundVarianceThreshold), ...
                num2str(app.BackgroundMaxPC), ...
                num2str(app.BackgroundReplicates), ...
                num2str(app.BackgroundRandomSeed)};
            
            answer = inputdlg( ...
                prompts, ...
                'Background PCA + K-means settings', ...
                [1 75], ...
                defaults);
            
            if isempty(answer)
                return
            end
            
            K = round(str2double(answer{1}));
            varianceTarget = str2double(answer{2});
            maxPC = round(str2double(answer{3}));
            replicates = round(str2double(answer{4}));
            randomSeed = round(str2double(answer{5}));
            
            if isnan(K) || K < 2
                error('K must be at least 2.');
            end
            
            if isnan(varianceTarget) || ...
                    varianceTarget <= 0 || ...
                    varianceTarget > 100
                
                error( ...
                    'Explained variance target must be in 0-100.');
            end
            
            if isnan(maxPC) || maxPC < 1
                error('Maximum PC must be at least 1.');
            end
            
            if isnan(replicates) || replicates < 1
                error('Replicates must be at least 1.');
            end
            
            app.BackgroundK = K;
            app.BackgroundVarianceThreshold = varianceTarget;
            app.BackgroundMaxPC = maxPC;
            app.BackgroundReplicates = replicates;
            app.BackgroundRandomSeed = randomSeed;
            
            % =========================================================
            % 2. PREPARE VALID SPECTRA
            % =========================================================
            
            cube = double(app.BackgroundInputCube);
            
            [ny, nx, nWn] = size(cube);
            
            validIdx = ...
                find(app.BackgroundValidMask2D);
            
            nValid = numel(validIdx);
            
            if nValid < K
                
                error( ...
                    'The number of valid pixels is smaller than K.');
            end
            
            Xall = ...
                reshape(cube, ny * nx, nWn);
            
            Xvalid = ...
                Xall(validIdx, :);
            
            goodRows = ...
                all(isfinite(Xvalid), 2);
            
            validIdx = validIdx(goodRows);
            Xvalid = Xvalid(goodRows, :);
            
            nValid = size(Xvalid, 1);
            
            if nValid < K
                
                error( ...
                    'Too few finite valid spectra remain.');
            end
            
            % =========================================================
            % 3. PRELIMINARY NORMALIZATION
            %
            % Remove spectrum-wise offset, then vector normalize.
            % This is not the final biochemical preprocessing.
            % =========================================================
            
            rowMedian = ...
                median(Xvalid, 2, 'omitnan');
            
            Xvalid = ...
                Xvalid - rowMedian;
            
            rowNorm = ...
                sqrt(sum(Xvalid .^ 2, 2));
            
            rowNorm(rowNorm <= eps | ~isfinite(rowNorm)) = 1;
            
            Xvalid = ...
                Xvalid ./ rowNorm;
            
            % =========================================================
            % 4. SELECT TRAINING SAMPLE
            % =========================================================
            
            rng(randomSeed);
            
            if nValid > app.BackgroundTrainingMaxSpectra
                
                trainLocalIdx = ...
                    randperm( ...
                    nValid, ...
                    app.BackgroundTrainingMaxSpectra);
                
            else
                
                trainLocalIdx = ...
                    1:nValid;
            end
            
            Xtrain = ...
                Xvalid(trainLocalIdx, :);
            
            % =========================================================
            % 5. PCA ON TRAINING SAMPLE
            % =========================================================
            
            [coeff, scoreTrain, ~, ~, explained, mu] = ...
                pca( ...
                Xtrain, ...
                'Centered', true, ...
                'Economy', true);
            
            cumulativeExplained = ...
                cumsum(explained);
            
            nPC = ...
                find( ...
                cumulativeExplained >= varianceTarget, ...
                1, ...
                'first');
            
            if isempty(nPC)
                nPC = size(coeff, 2);
            end
            
            nPC = ...
                min([ ...
                nPC, ...
                maxPC, ...
                size(coeff, 2)]);
            
            nPC = max(1, nPC);
            
            app.BackgroundPCAcoeff = coeff;
            app.BackgroundPCAmu = mu;
            app.BackgroundExplained = explained;
            app.BackgroundNumPCUsed = nPC;
            
            scoreTrainUse = ...
                scoreTrain(:, 1:nPC);
            
            % =========================================================
            % 6. K-MEANS ON TRAINING SAMPLE
            % =========================================================
            
            rng(randomSeed);
            
            [labelTrain, centroids] = ...
                kmeans( ...
                scoreTrainUse, ...
                K, ...
                'Replicates', replicates, ...
                'MaxIter', 500, ...
                'Display', 'off', ...
                'EmptyAction', 'singleton');
            
            % =========================================================
            % 7. PROJECT ALL VALID SPECTRA INTO PCA SPACE
            % =========================================================
            
            scoreAll = ...
                (Xvalid - mu) * coeff(:, 1:nPC);
            
            % Assign all valid pixels to nearest centroid.
            distances = ...
                pdist2(scoreAll, centroids, 'squaredeuclidean');
            
            [d1, labelAll] = ...
                min(distances, [], 2);
            
            sortedDist = ...
                sort(distances, 2, 'ascend');
            
            if size(sortedDist, 2) >= 2
                
                d2 = sortedDist(:, 2);
                
                confidence = ...
                    1 - sqrt(d1) ./ max(sqrt(d2), eps);
                
            else
                
                confidence = ones(size(d1));
            end
            
            confidence = ...
                max(0, min(1, confidence));
            
            % =========================================================
            % 8. SAVE HISTORY BEFORE REPLACING RESULT
            % =========================================================
            
            app.pushBackgroundHistory();
            
            % =========================================================
            % 9. BUILD LABEL MAP
            % =========================================================
            
            labelMap = ...
                zeros(ny, nx);
            
            confidenceMap = ...
                nan(ny, nx);
            
            labelMap(validIdx) = ...
                labelAll;
            
            confidenceMap(validIdx) = ...
                confidence;
            
            app.BackgroundLabelMap = labelMap;
            app.BackgroundConfidenceMap = confidenceMap;
            
            app.BackgroundManualClassMap = ...
                zeros(ny, nx, 'uint8');
            
            % =========================================================
            % 10. BUILD CLUSTER STRUCTURE
            % =========================================================
            
            clusters = app.makeEmptyBackgroundClusters();
            nextID = 1;
            
            for k = 1:K
                
                pix = ...
                    validIdx(labelAll == k);
                
                if isempty(pix)
                    continue
                end
                
                clusterSpectra = ...
                    Xall(pix, :);
                
                C = struct();
                
                C.ID = nextID;
                C.Name = sprintf('Cluster %d', nextID);
                C.ParentID = 0;
                C.PixelIdx = pix(:);
                C.Class = 'Unassigned';
                C.Active = true;
                
                C.MeanSpectrum = ...
                    mean(clusterSpectra, 1, 'omitnan');
                
                C.StdSpectrum = ...
                    std(clusterSpectra, 0, 1, 'omitnan');
                C.Metrics = struct();
                
                clusters(end + 1) = C; %#ok<AGROW>
                
                nextID = nextID + 1;
            end
            
            app.BackgroundClusters = clusters;
            app.BackgroundNextClusterID = nextID;
            
            app.BackgroundValidated = false;
            app.BackgroundConfirmed = false;
            
            app.rebuildBackgroundMasks();
            % Compute scientific global and per-cluster metrics.
            app.computeBackgroundScientificMetrics();
            app.populateBackgroundTree();
            app.updateBackgroundInfo();
            app.updateBackgroundQC();
            
            app.DropDown.Value = ...
                'Initial clusters';
            
            app.displayBackgroundView();
            
            uialert( ...
                app.UIFigure, ...
                { ...
                'Initial background segmentation completed.'; ...
                ''; ...
                sprintf('Valid spectra: %d', nValid); ...
                sprintf('Training spectra: %d', size(Xtrain, 1)); ...
                sprintf('Clusters: %d', numel(clusters)); ...
                sprintf('PCs used: %d', nPC); ...
                sprintf('Explained variance: %.2f%%', ...
                sum(explained(1:nPC))); ...
                ''; ...
                'Select each cluster in the Tree and assign it'; ...
                'to Cell, Background or Uncertain.'}, ...
                'Segmentation completed', ...
                'Icon', 'success');
        end
        
        
        function runBackgroundIntensityThreshold(app)
            
            mapImg = app.BackgroundPreviewMap;
            validMask = app.BackgroundValidMask2D;
            
            vals = mapImg(validMask);
            vals = vals(isfinite(vals));
            
            if isempty(vals)
                error('No valid preview intensity values.');
            end
            
            lo = prctile(vals, 2);
            hi = prctile(vals, 98);
            
            if hi <= lo
                error('Preview intensity map has no usable contrast.');
            end
            
            normMap = ...
                (mapImg - lo) ./ (hi - lo);
            
            normMap = ...
                max(0, min(1, normMap));
            
            thresholdValue = ...
                graythresh(normMap(validMask));
            
            candidateCell = ...
                validMask & normMap > thresholdValue;
            
            candidateBackground = ...
                validMask & ~candidateCell;
            
            app.pushBackgroundHistory();
            
            [ny, nx, nWn] = ...
                size(app.BackgroundInputCube);
            
            Xall = ...
                reshape( ...
                app.BackgroundInputCube, ...
                ny * nx, ...
                nWn);
            
            clusters = app.makeEmptyBackgroundClusters();
            
            pix1 = find(candidateBackground);
            pix2 = find(candidateCell);
            
            C1.ID = 1;
            C1.Name = 'Low-intensity candidate';
            C1.ParentID = 0;
            C1.PixelIdx = pix1;
            C1.Class = 'Unassigned';
            C1.Active = true;
            C1.MeanSpectrum = mean(Xall(pix1, :), 1, 'omitnan');
            C1.StdSpectrum = std(Xall(pix1, :), 0, 1, 'omitnan');
            C1.Metrics = struct();
            
            C2.ID = 2;
            C2.Name = 'High-intensity candidate';
            C2.ParentID = 0;
            C2.PixelIdx = pix2;
            C2.Class = 'Unassigned';
            C2.Active = true;
            C2.MeanSpectrum = mean(Xall(pix2, :), 1, 'omitnan');
            C2.StdSpectrum = std(Xall(pix2, :), 0, 1, 'omitnan');
            C2.Metrics = struct();
            
            clusters(1) = C1;
            clusters(2) = C2;
            
            labelMap = zeros(ny, nx);
            labelMap(pix1) = 1;
            labelMap(pix2) = 2;
            
            app.BackgroundClusters = clusters;
            app.BackgroundNextClusterID = 3;
            app.BackgroundLabelMap = labelMap;
            app.BackgroundConfidenceMap = nan(ny, nx);
            app.BackgroundManualClassMap = zeros(ny, nx, 'uint8');
            
            app.BackgroundValidated = false;
            
            app.rebuildBackgroundMasks();
            % Compute scientific global and per-cluster metrics.
            app.computeBackgroundScientificMetrics();
            app.populateBackgroundTree();
            app.updateBackgroundInfo();
            app.updateBackgroundQC();
            
            app.DropDown.Value = 'Initial clusters';
            app.displayBackgroundView();
        end
        
        
        function populateBackgroundTree(app)
            
            app.BackgroundIsUpdatingTree = true;
            
            try
                delete(app.Tree2.Children);
            catch
            end
            
            root = uitreenode( ...
                app.Tree2, ...
                'Text', 'Background segmentation', ...
                'NodeData', struct( ...
                'Type', 'root'));
            
            unassignedRoot = uitreenode( ...
                root, ...
                'Text', 'Unassigned', ...
                'NodeData', struct( ...
                'Type', 'class', ...
                'Class', 'Unassigned'));
            
            cellRoot = uitreenode( ...
                root, ...
                'Text', 'Cell', ...
                'NodeData', struct( ...
                'Type', 'class', ...
                'Class', 'Cell'));
            
            backgroundRoot = uitreenode( ...
                root, ...
                'Text', 'Background', ...
                'NodeData', struct( ...
                'Type', 'class', ...
                'Class', 'Background'));
            
            uncertainRoot = uitreenode( ...
                root, ...
                'Text', 'Uncertain', ...
                'NodeData', struct( ...
                'Type', 'class', ...
                'Class', 'Uncertain'));
            
            if isempty(app.BackgroundClusters)
                
                expand(root);
                app.BackgroundIsUpdatingTree = false;
                return
            end
            
            activeMask = [app.BackgroundClusters.Active];
            activeClusters = app.BackgroundClusters(activeMask);
            
            % Sort suspicious clusters first within the same class.
            priorityValues = zeros(numel(activeClusters), 1);
            
            for i = 1:numel(activeClusters)
                
                if isfield(activeClusters(i), 'Metrics') && ...
                        ~isempty(activeClusters(i).Metrics) && ...
                        isfield( ...
                        activeClusters(i).Metrics, ...
                        'SplitPriority')
                    
                    priorityValues(i) = ...
                        activeClusters(i).Metrics.SplitPriority;
                end
            end
            
            [~, order] = sort(priorityValues, 'descend');
            activeClusters = activeClusters(order);
            
            for i = 1:numel(activeClusters)
                
                C = activeClusters(i);
                
                splitLevel = 'NOT COMPUTED';
                splitScore = NaN;
                meanSilhouette = NaN;
                stability = NaN;
                
                if isfield(C, 'Metrics') && ...
                        ~isempty(C.Metrics)
                    
                    M = C.Metrics;
                    
                    if isfield(M, 'SplitLevel')
                        splitLevel = M.SplitLevel;
                    end
                    
                    if isfield(M, 'SplitPriority')
                        splitScore = M.SplitPriority;
                    end
                    
                    if isfield(M, 'MeanSilhouette')
                        meanSilhouette = M.MeanSilhouette;
                    end
                    
                    if isfield(M, 'Stability')
                        stability = M.Stability;
                    end
                end
                
                nodeText = sprintf( ...
                    ['%s | %d px | Split %s %.2f | ' ...
                    'Sil %.2f | Stab %.2f'], ...
                    C.Name, ...
                    numel(C.PixelIdx), ...
                    splitLevel, ...
                    splitScore, ...
                    meanSilhouette, ...
                    stability);
                
                switch C.Class
                    
                    case 'Cell'
                        parentNode = cellRoot;
                        
                    case 'Background'
                        parentNode = backgroundRoot;
                        
                    case 'Uncertain'
                        parentNode = uncertainRoot;
                        
                    otherwise
                        parentNode = unassignedRoot;
                end
                
                uitreenode( ...
                    parentNode, ...
                    'Text', nodeText, ...
                    'NodeData', struct( ...
                    'Type', 'cluster', ...
                    'ID', C.ID));
            end
            
            expand(root);
            expand(unassignedRoot);
            expand(cellRoot);
            expand(backgroundRoot);
            expand(uncertainRoot);
            
            app.BackgroundIsUpdatingTree = false;
        end
        
        function plotSelectedBackgroundClusters(app, clusterIDs)
            
            if isempty(clusterIDs)
                return
            end
            
            % =========================================================
            % AXIS 1: SPATIAL HIGHLIGHT
            % =========================================================
            
            ax1 = app.UIAxes5;
            
            app.resetImageAxis(ax1);
            
            imagesc(ax1, app.BackgroundPreviewMap);
            
            axis(ax1, 'image');
            ax1.YDir = 'normal';
            colormap(ax1, 'gray');
            colorbar(ax1);
            
            selectedMask = ...
                false(size(app.BackgroundValidMask2D));
            
            for id = clusterIDs(:).'
                
                idx = app.findBackgroundClusterIndex(id);
                
                if isempty(idx)
                    continue
                end
                
                selectedMask( ...
                    app.BackgroundClusters(idx).PixelIdx) = true;
            end
            
            hold(ax1, 'on');
            
            redImage = cat( ...
                3, ...
                ones(size(selectedMask)), ...
                zeros(size(selectedMask)), ...
                zeros(size(selectedMask)));
            
            h = imagesc(ax1, redImage);
            
            set( ...
                h, ...
                'AlphaData', ...
                0.55 * double(selectedMask));
            
            hold(ax1, 'off');
            
            title( ...
                ax1, ...
                sprintf( ...
                'Selected cluster pixels | clusters=%d | pixels=%d', ...
                numel(clusterIDs), ...
                nnz(selectedMask)));
            
            % =========================================================
            % AXIS 2: MEAN ± SD
            % =========================================================
            
            ax2 = app.UIAxes6;
            
            app.resetSpectrumAxis(ax2);
            
            hold(ax2, 'on');
            
            legendText = {};
            
            yAll = [];
            
            for id = clusterIDs(:).'
                
                idx = app.findBackgroundClusterIndex(id);
                
                if isempty(idx)
                    continue
                end
                
                C = app.BackgroundClusters(idx);
                
                m = C.MeanSpectrum(:);
                s = C.StdSpectrum(:);
                wn = app.Wavenumber(:);
                
                upper = m + s;
                lower = m - s;
                
                fill( ...
                    ax2, ...
                    [wn; flipud(wn)], ...
                    [upper; flipud(lower)], ...
                    [0.75 0.75 0.75], ...
                    'FaceAlpha', 0.12, ...
                    'EdgeColor', 'none', ...
                    'HandleVisibility', 'off');
                
                plot( ...
                    ax2, ...
                    wn, ...
                    m, ...
                    'LineWidth', 1.5);
                
                legendText{end + 1} = ...
                    sprintf('%s | %s', C.Name, C.Class); %#ok<AGROW>
                
                yAll = [yAll; upper; lower]; %#ok<AGROW>
            end
            
            hold(ax2, 'off');
            
            if ~isempty(legendText)
                legend(ax2, legendText, 'Location', 'best');
            end
            
            title(ax2, 'Selected cluster mean spectra');
            
            app.setNiceSpectralLimits( ...
                ax2, ...
                app.Wavenumber, ...
                yAll);
            
            app.updateBackgroundSelectedInfo(clusterIDs);
        end
        
        
        function idx = findBackgroundClusterIndex(app, clusterID)
            
            idx = [];
            
            if isempty(app.BackgroundClusters)
                return
            end
            
            allIDs = ...
                [app.BackgroundClusters.ID];
            
            idx = ...
                find(allIDs == clusterID, 1, 'first');
        end
        
        
        function rebuildBackgroundMasks(app)
            
            if isempty(app.BackgroundValidMask2D)
                return
            end
            
            sz = size(app.BackgroundValidMask2D);
            
            cellMask = false(sz);
            backgroundMask = false(sz);
            uncertainMask = false(sz);
            
            activeClusters = ...
                app.BackgroundClusters( ...
                [app.BackgroundClusters.Active]);
            
            for i = 1:numel(activeClusters)
                
                C = activeClusters(i);
                
                switch C.Class
                    
                    case 'Cell'
                        cellMask(C.PixelIdx) = true;
                        
                    case 'Background'
                        backgroundMask(C.PixelIdx) = true;
                        
                    case 'Uncertain'
                        uncertainMask(C.PixelIdx) = true;
                end
            end
            
            % Manual override has final priority.
            if ~isempty(app.BackgroundManualClassMap)
                
                manualCell = ...
                    app.BackgroundManualClassMap == 1;
                
                manualBackground = ...
                    app.BackgroundManualClassMap == 2;
                
                manualUncertain = ...
                    app.BackgroundManualClassMap == 3;
                
                manualAny = ...
                    manualCell | ...
                    manualBackground | ...
                    manualUncertain;
                
                cellMask(manualAny) = false;
                backgroundMask(manualAny) = false;
                uncertainMask(manualAny) = false;
                
                cellMask(manualCell) = true;
                backgroundMask(manualBackground) = true;
                uncertainMask(manualUncertain) = true;
            end
            
            % Enforce valid-area limitation.
            cellMask = ...
                cellMask & app.BackgroundValidMask2D;
            
            backgroundMask = ...
                backgroundMask & app.BackgroundValidMask2D;
            
            uncertainMask = ...
                uncertainMask & app.BackgroundValidMask2D;
            
            % Enforce exclusivity.
            backgroundMask(cellMask) = false;
            
            uncertainMask( ...
                cellMask | backgroundMask) = false;
            
            assigned = ...
                cellMask | ...
                backgroundMask | ...
                uncertainMask;
            
            unassignedMask = ...
                app.BackgroundValidMask2D & ~assigned;
            
            app.BackgroundCellMask2D = cellMask;
            app.BackgroundBackgroundMask2D = backgroundMask;
            app.BackgroundUncertainMask2D = uncertainMask;
            app.BackgroundUnassignedMask2D = unassignedMask;
        end
        
        function displayBackgroundView(app)
            
            if ~app.BackgroundReady
                return
            end
            
            viewValue = ...
                char(app.DropDown.Value);
            
            ax = app.UIAxes5;
            
            app.resetImageAxis(ax);
            
            switch viewValue
                
                case 'Initial clusters'
                    
                    img = ...
                        app.BackgroundLabelMap;
                    
                    img(~app.BackgroundValidMask2D) = NaN;
                    
                    imagesc(ax, img);
                    axis(ax, 'image');
                    ax.YDir = 'normal';
                    colormap(ax, 'hot');
                    colorbar(ax);
                    
                    title(ax, ...
                        'Initial spectral clusters');
                    
                case 'Assigned classes'
                    
                    classMap = ...
                        app.buildBackgroundClassMap();
                    
                    imagesc(ax, classMap);
                    axis(ax, 'image');
                    ax.YDir = 'normal';
                    
                    colormap(ax, [ ...
                        0.15 0.15 0.15; ... % invalid
                        0.95 0.85 0.15; ... % unassigned
                        0.15 0.75 0.20; ... % cell
                        0.20 0.35 0.85; ... % background
                        0.85 0.25 0.75]);   % uncertain
                    
                    caxis(ax, [0 4]);
                    colorbar(ax);
                    
                    title(ax, ...
                        'Assigned classes');
                    
                case 'Confidence map'
                    
                    img = ...
                        app.BackgroundConfidenceMap;
                    
                    img(~app.BackgroundValidMask2D) = NaN;
                    
                    imagesc(ax, img);
                    axis(ax, 'image');
                    ax.YDir = 'normal';
                    colormap(ax, 'parula');
                    colorbar(ax);
                    caxis(ax, [0 1]);
                    
                    title(ax, ...
                        'Nearest-centroid confidence');
                    
                case 'Cell mask'
                    
                    imagesc(ax, ...
                        double(app.BackgroundCellMask2D));
                    
                    axis(ax, 'image');
                    ax.YDir = 'normal';
                    colormap(ax, 'gray');
                    caxis(ax, [0 1]);
                    
                    title(ax, ...
                        sprintf( ...
                        'Cell mask | pixels=%d', ...
                        nnz(app.BackgroundCellMask2D)));
                    
                case 'Background mask'
                    
                    imagesc(ax, ...
                        double(app.BackgroundBackgroundMask2D));
                    
                    axis(ax, 'image');
                    ax.YDir = 'normal';
                    colormap(ax, 'gray');
                    caxis(ax, [0 1]);
                    
                    title(ax, ...
                        sprintf( ...
                        'Background mask | pixels=%d', ...
                        nnz(app.BackgroundBackgroundMask2D)));
                    
                case 'Uncertain mask'
                    
                    imagesc(ax, ...
                        double(app.BackgroundUncertainMask2D));
                    
                    axis(ax, 'image');
                    ax.YDir = 'normal';
                    colormap(ax, 'gray');
                    caxis(ax, [0 1]);
                    
                    title(ax, ...
                        sprintf( ...
                        'Uncertain mask | pixels=%d', ...
                        nnz(app.BackgroundUncertainMask2D)));
                    
                case 'Unassigned mask'
                    
                    imagesc(ax, ...
                        double(app.BackgroundUnassignedMask2D));
                    
                    axis(ax, 'image');
                    ax.YDir = 'normal';
                    colormap(ax, 'gray');
                    caxis(ax, [0 1]);
                    
                    title(ax, ...
                        sprintf( ...
                        'Unassigned mask | pixels=%d', ...
                        nnz(app.BackgroundUnassignedMask2D)));
            end
            
            box(ax, 'on');
        end
        
        
        function classMap = buildBackgroundClassMap(app)
            
            classMap = ...
                zeros(size(app.BackgroundValidMask2D));
            
            classMap( ...
                app.BackgroundValidMask2D) = 1;
            
            classMap( ...
                app.BackgroundCellMask2D) = 2;
            
            classMap( ...
                app.BackgroundBackgroundMask2D) = 3;
            
            classMap( ...
                app.BackgroundUncertainMask2D) = 4;
        end


        function pushBackgroundHistory(app)
            
            state = app.captureBackgroundState();
            
            app.BackgroundUndoStack{end + 1} = state;
            
            if numel(app.BackgroundUndoStack) > ...
                    app.BackgroundMaxHistory
                
                app.BackgroundUndoStack(1) = [];
            end
            
            app.BackgroundRedoStack = {};
        end
        
        
        function state = captureBackgroundState(app)
            
            state = struct();
            
            state.BackgroundClusters = ...
                app.BackgroundClusters;
            
            state.BackgroundNextClusterID = ...
                app.BackgroundNextClusterID;
            
            state.BackgroundLabelMap = ...
                app.BackgroundLabelMap;
            
            state.BackgroundConfidenceMap = ...
                app.BackgroundConfidenceMap;
            
            state.BackgroundManualClassMap = ...
                app.BackgroundManualClassMap;
            
            state.BackgroundCellMask2D = ...
                app.BackgroundCellMask2D;
            
            state.BackgroundBackgroundMask2D = ...
                app.BackgroundBackgroundMask2D;
            
            state.BackgroundUncertainMask2D = ...
                app.BackgroundUncertainMask2D;
            
            state.BackgroundUnassignedMask2D = ...
                app.BackgroundUnassignedMask2D;
            
            state.BackgroundSelectedClusterIDs = ...
                app.BackgroundSelectedClusterIDs;
            
            state.BackgroundValidated = ...
                app.BackgroundValidated;
        end
        
        
        function restoreBackgroundState(app, state)
            
            app.BackgroundClusters = ...
                state.BackgroundClusters;
            
            app.BackgroundNextClusterID = ...
                state.BackgroundNextClusterID;
            
            app.BackgroundLabelMap = ...
                state.BackgroundLabelMap;
            
            app.BackgroundConfidenceMap = ...
                state.BackgroundConfidenceMap;
            
            app.BackgroundManualClassMap = ...
                state.BackgroundManualClassMap;
            
            app.BackgroundCellMask2D = ...
                state.BackgroundCellMask2D;
            
            app.BackgroundBackgroundMask2D = ...
                state.BackgroundBackgroundMask2D;
            
            app.BackgroundUncertainMask2D = ...
                state.BackgroundUncertainMask2D;
            
            app.BackgroundUnassignedMask2D = ...
                state.BackgroundUnassignedMask2D;
            
            app.BackgroundSelectedClusterIDs = ...
                state.BackgroundSelectedClusterIDs;
            
            app.BackgroundValidated = ...
                state.BackgroundValidated;
            
            app.populateBackgroundTree();
            app.updateBackgroundInfo();
            app.updateBackgroundQC();
            app.displayBackgroundView();
        end
        
        function plotCellBackgroundSpectralComparison(app)
            
            app.rebuildBackgroundMasks();
            
            cellIdx = ...
                find(app.BackgroundCellMask2D);
            
            backgroundIdx = ...
                find(app.BackgroundBackgroundMask2D);
            
            if isempty(cellIdx) || isempty(backgroundIdx)
                
                uialert( ...
                    app.UIFigure, ...
                    ['Both Cell and Background masks are required ' ...
                    'for spectral comparison.'], ...
                    'Spectral comparison');
                
                return
            end
            
            [ny, nx, nWn] = ...
                size(app.BackgroundInputCube);
            
            X = ...
                reshape( ...
                app.BackgroundInputCube, ...
                ny * nx, ...
                nWn);
            
            maxN = 10000;
            
            if numel(cellIdx) > maxN
                rng(1);
                cellIdx = ...
                    cellIdx(randperm(numel(cellIdx), maxN));
            end
            
            if numel(backgroundIdx) > maxN
                rng(2);
                backgroundIdx = ...
                    backgroundIdx( ...
                    randperm(numel(backgroundIdx), maxN));
            end
            
            cellMean = ...
                mean(X(cellIdx, :), 1, 'omitnan');
            
            backgroundMean = ...
                mean(X(backgroundIdx, :), 1, 'omitnan');
            
            ax = app.UIAxes6;
            
            app.resetSpectrumAxis(ax);
            
            plot( ...
                ax, ...
                app.Wavenumber, ...
                cellMean, ...
                'LineWidth', 1.6, ...
                'DisplayName', 'Cell mean');
            
            hold(ax, 'on');
            
            plot( ...
                ax, ...
                app.Wavenumber, ...
                backgroundMean, ...
                'LineWidth', 1.6, ...
                'DisplayName', 'Background mean');
            
            
            hold(ax, 'off');
            
            legend(ax, 'Location', 'best');
            
            title(ax, ...
                'Cell versus background spectral comparison');
            
            app.setNiceSpectralLimits( ...
                ax, ...
                app.Wavenumber, ...
                [cellMean(:); backgroundMean(:)]);
        end
        
        
        function [isValid, messages] = validateBackgroundResult(app)
            
            app.rebuildBackgroundMasks();
            
            messages = {};
            
            isValid = true;
            
            if isempty(app.BackgroundCellMask2D) || ...
                    ~any(app.BackgroundCellMask2D(:))
                
                messages{end + 1} = ...
                    'FAIL: Cell mask is empty.';
                
                isValid = false;
            end
            
            if isempty(app.BackgroundBackgroundMask2D) || ...
                    ~any(app.BackgroundBackgroundMask2D(:))
                
                messages{end + 1} = ...
                    'FAIL: Background mask is empty.';
                
                isValid = false;
            end
            
            overlapCellBackground = ...
                app.BackgroundCellMask2D & ...
                app.BackgroundBackgroundMask2D;
            
            overlapCellUncertain = ...
                app.BackgroundCellMask2D & ...
                app.BackgroundUncertainMask2D;
            
            overlapBackgroundUncertain = ...
                app.BackgroundBackgroundMask2D & ...
                app.BackgroundUncertainMask2D;
            
            if any(overlapCellBackground(:))
                
                messages{end + 1} = ...
                    'FAIL: Cell and Background masks overlap.';
                
                isValid = false;
            end
            
            if any(overlapCellUncertain(:))
                
                messages{end + 1} = ...
                    'FAIL: Cell and Uncertain masks overlap.';
                
                isValid = false;
            end
            
            if any(overlapBackgroundUncertain(:))
                
                messages{end + 1} = ...
                    'FAIL: Background and Uncertain masks overlap.';
                
                isValid = false;
            end
            
            nUnassigned = ...
                nnz(app.BackgroundUnassignedMask2D);
            
            if nUnassigned > 0
                
                messages{end + 1} = sprintf( ...
                    'FAIL: %d valid pixels remain unassigned.', ...
                    nUnassigned);
                
                isValid = false;
            end
            
            if ~isequal( ...
                    size(app.BackgroundCellMask2D), ...
                    size(app.BackgroundValidMask2D))
                
                messages{end + 1} = ...
                    'FAIL: Mask dimensions do not match.';
                
                isValid = false;
            end
            
            invalidAssigned = ...
                ( ...
                app.BackgroundCellMask2D | ...
                app.BackgroundBackgroundMask2D | ...
                app.BackgroundUncertainMask2D) & ...
                ~app.BackgroundValidMask2D;
            
            if any(invalidAssigned(:))
                
                messages{end + 1} = ...
                    'FAIL: Invalid artifact pixels were assigned.';
                
                isValid = false;
            end
            
            if isValid
                
                messages = { ...
                    'PASS: Background segmentation is valid.'; ...
                    ''; ...
                    sprintf('Cell pixels: %d', ...
                    nnz(app.BackgroundCellMask2D)); ...
                    sprintf('Background pixels: %d', ...
                    nnz(app.BackgroundBackgroundMask2D)); ...
                    sprintf('Uncertain pixels: %d', ...
                    nnz(app.BackgroundUncertainMask2D)); ...
                    sprintf('Artifact pixels excluded: %d', ...
                    nnz(app.BackgroundArtifactMask2D))};
            end
        end
        
        
        function updateBackgroundInfo(app)
            
            if ~app.BackgroundReady
                
                app.TextArea_3.Value = { ...
                    'BACKGROUND REMOVAL'; ...
                    ''; ...
                    'Waiting for Artifact removal result.'};
                
                return
            end
            
            [ny, nx, nWn] = ...
                size(app.BackgroundInputCube);
            
            nActiveClusters = 0;
            
            if ~isempty(app.BackgroundClusters)
                
                nActiveClusters = ...
                    nnz([app.BackgroundClusters.Active]);
            end
            
            app.TextArea_3.Value = { ...
                'SCAN INFORMATION'; ...
                '================================'; ...
                ''; ...
                ['Scan: ' app.CurrentScanName]; ...
                sprintf('Cube: %d x %d x %d', ...
                ny, nx, nWn); ...
                sprintf('WN: %.2f - %.2f cm^{-1}', ...
                min(app.Wavenumber), ...
                max(app.Wavenumber)); ...
                ''; ...
                'Input stage: Artifact confirmed'; ...
                sprintf('Valid pixels: %d', ...
                nnz(app.BackgroundValidMask2D)); ...
                sprintf('Artifact pixels excluded: %d', ...
                nnz(app.BackgroundArtifactMask2D)); ...
                ''; ...
                ['Morphology: ' ...
                char(app.MorphologyDropDown.Value)]; ...
                ['Method: ' ...
                char(app.SegmethodDropDown.Value)]; ...
                sprintf('Active clusters: %d', ...
                nActiveClusters)};
        end
        
        
        function updateBackgroundSelectedInfo(app, ids)
            
            oldText = app.TextArea_3.Value;
            
            if ischar(oldText) || isstring(oldText)
                oldText = cellstr(oldText);
            end
            
            lines = oldText(:);
            
            lines{end + 1} = '';
            lines{end + 1} = 'SELECTED CLUSTERS';
            lines{end + 1} = '================================';
            
            for id = ids(:).'
                
                idx = ...
                    app.findBackgroundClusterIndex(id);
                
                if isempty(idx)
                    continue
                end
                
                C = app.BackgroundClusters(idx);
                
                lines{end + 1} = sprintf( ...
                    '%s | Class=%s | Pixels=%d', ...
                    C.Name, ...
                    C.Class, ...
                    numel(C.PixelIdx));
            end
            
            app.TextArea_3.Value = lines;
        end
        
        
        function updateBackgroundQC(app)
            
            if ~app.BackgroundReady
                
                app.TextArea_4.Value = { ...
                    'QC PARAMETERS'; ...
                    ''; ...
                    'No background input.'};
                
                return
            end
            
            app.rebuildBackgroundMasks();
            
            nValid = nnz(app.BackgroundValidMask2D);
            nCell = nnz(app.BackgroundCellMask2D);
            nBackground = nnz(app.BackgroundBackgroundMask2D);
            nUncertain = nnz(app.BackgroundUncertainMask2D);
            nUnassigned = nnz(app.BackgroundUnassignedMask2D);
            
            if app.BackgroundValidated
                validationText = 'PASS';
            else
                validationText = 'NOT VALIDATED';
            end
            
            lines = { ...
                'BACKGROUND SCIENTIFIC QC'; ...
                '================================'; ...
                ''; ...
                'TIER 1 — STRUCTURAL QC'; ...
                sprintf('Valid pixels: %d', nValid); ...
                sprintf('Cell pixels: %d (%.2f%%)', ...
                nCell, 100 * nCell / max(nValid, 1)); ...
                sprintf('Background pixels: %d (%.2f%%)', ...
                nBackground, ...
                100 * nBackground / max(nValid, 1)); ...
                sprintf('Uncertain pixels: %d (%.2f%%)', ...
                nUncertain, ...
                100 * nUncertain / max(nValid, 1)); ...
                sprintf('Unassigned pixels: %d', nUnassigned); ...
                ['Structural validation: ' validationText]};
            
            G = app.BackgroundGlobalMetrics;
            
            if ~isempty(G) && ...
                    isfield(G, 'NumberOfClusters')
                
                lines = [lines; { ...
                    ''; ...
                    'TIER 2 — GLOBAL CLUSTERING QC'; ...
                    sprintf('Active clusters: %d', ...
                    G.NumberOfClusters); ...
                    sprintf('Macro silhouette: %.3f', ...
                    G.MacroSilhouette); ...
                    sprintf('Micro silhouette: %.3f', ...
                    G.MicroSilhouette); ...
                    sprintf('Davies-Bouldin: %.3f', ...
                    G.DaviesBouldin); ...
                    sprintf('Calinski-Harabasz: %.3f', ...
                    G.CalinskiHarabasz); ...
                    sprintf('Repeated-run stability: %.3f', ...
                    G.MeanStability); ...
                    sprintf('Unstable pixels < 0.80: %.2f%%', ...
                    100 * G.UnstableFraction); ...
                    sprintf('Metric pixels: %d', ...
                    G.MetricPixels); ...
                    sprintf('Silhouette sample: %d', ...
                    G.SilhouetteSamplePixels); ...
                    sprintf('PCs used: %d', ...
                    G.NumPCUsed); ...
                    sprintf('Explained variance: %.2f%%', ...
                    G.ExplainedVarianceUsed)}];
            else
                
                lines = [lines; { ...
                    ''; ...
                    'TIER 2 — GLOBAL CLUSTERING QC'; ...
                    'Metrics have not been computed.'}];
            end
            
            % =========================================================
            % SELECTED CLUSTER QC
            % =========================================================
            
            selectedIDs = ...
                app.BackgroundSelectedClusterIDs;
            
            if numel(selectedIDs) == 1
                
                metricIdx = find( ...
                    [app.BackgroundClusterMetrics.ClusterID] == ...
                    selectedIDs(1), ...
                    1);
                
                if ~isempty(metricIdx)
                    
                    M = app.BackgroundClusterMetrics(metricIdx);
                    
                    lines = [lines; { ...
                        ''; ...
                        'TIER 3 — SELECTED CLUSTER QC'; ...
                        sprintf('Cluster: %s (ID %d)', ...
                        M.ClusterName, M.ClusterID); ...
                        sprintf('Pixels: %d (%.2f%%)', ...
                        M.PixelCount, ...
                        100 * M.PixelFraction); ...
                        sprintf('Mean silhouette: %.3f', ...
                        M.MeanSilhouette); ...
                        sprintf('Median silhouette: %.3f', ...
                        M.MedianSilhouette); ...
                        sprintf('P10 silhouette: %.3f', ...
                        M.P10Silhouette); ...
                        sprintf('Negative silhouette: %.2f%%', ...
                        100 * M.NegativeSilhouetteFraction); ...
                        sprintf('Median confidence: %.3f', ...
                        M.MedianConfidence); ...
                        sprintf('Low confidence < 0.15: %.2f%%', ...
                        100 * M.LowConfidenceFraction); ...
                        sprintf('Normalized dispersion: %.3f x', ...
                        M.NormalizedDispersion); ...
                        sprintf('Spectral angle median/P90: %.4f / %.4f rad', ...
                        M.MedianSpectralAngle, ...
                        M.P90SpectralAngle); ...
                        sprintf('Repeated-run stability: %.3f', ...
                        M.Stability); ...
                        sprintf('Connected components: %d', ...
                        M.ConnectedComponents); ...
                        sprintf('Largest component fraction: %.2f%%', ...
                        100 * M.LargestComponentFraction); ...
                        sprintf('Tiny isolated fraction: %.2f%%', ...
                        100 * M.IsolatedPixelFraction); ...
                        ''; ...
                        sprintf('SPLIT PRIORITY: %s (%.3f)', ...
                        M.SplitLevel, ...
                        M.SplitPriority); ...
                        ['Recommendation: ' M.Recommendation]; ...
                        ''; ...
                        'Main reasons:'}];
                    
                    for r = 1:numel(M.Reasons)
                        
                        lines{end + 1, 1} = ...
                            ['- ' M.Reasons{r}];
                    end
                end
            else
                
                lines = [lines; { ...
                    ''; ...
                    'TIER 3 — SELECTED CLUSTER QC'; ...
                    'Select exactly one cluster in the Tree'; ...
                    'to inspect its split diagnostics.'}];
            end
            
            app.TextArea_4.Value = lines;
        end
        
        function value = getBackgroundExplainedUsed(app)
            
            value = NaN;
            
            if isempty(app.BackgroundExplained) || ...
                    app.BackgroundNumPCUsed < 1
                
                return
            end
            
            n = min( ...
                app.BackgroundNumPCUsed, ...
                numel(app.BackgroundExplained));
            
            value = ...
                sum(app.BackgroundExplained(1:n));
        end


        function [cube, wn] = loadCubeFromCheckpoint(app, filePath)
            
            filePath = ...
                char(string(filePath));
            
            if isempty(filePath) || ...
                    ~isfile(filePath)
                
                error( ...
                    'Spectral checkpoint does not exist: %s', ...
                    filePath);
            end
            
            info = ...
                whos( ...
                '-file', ...
                filePath);
            
            variableNames = ...
                {info.name};
            
            R = struct();
            
            % =============================================================
            % COSMIC
            % =============================================================
            
            if any(strcmp(variableNames, 'RamanDataCosmic'))
                
                T = ...
                    load( ...
                    filePath, ...
                    'RamanDataCosmic');
                
                R = ...
                    T.RamanDataCosmic;
                
                % =============================================================
                % ORIGINAL READABLE
                % =============================================================
                
            elseif any(strcmp(variableNames, 'RamanData'))
                
                T = ...
                    load( ...
                    filePath, ...
                    'RamanData');
                
                R = ...
                    T.RamanData;
                
                % =============================================================
                % LEGACY ARTIFACT
                % =============================================================
                
            elseif any(strcmp(variableNames, 'RamanDataArtifact'))
                
                T = ...
                    load( ...
                    filePath, ...
                    'RamanDataArtifact');
                
                R = ...
                    T.RamanDataArtifact;
                
                % =============================================================
                % LEGACY BACKGROUND
                % =============================================================
                
            elseif any(strcmp(variableNames, 'RamanDataBackground'))
                
                T = ...
                    load( ...
                    filePath, ...
                    'RamanDataBackground');
                
                R = ...
                    T.RamanDataBackground;
                
            else
                
                error( ...
                    ['The selected file contains no recognized ' ...
                    'RamanData structure.']);
            end
            
            % =============================================================
            % MATERIALIZED CUBE
            % =============================================================
            
            if isfield(R, 'Cube') && ...
                    ~isempty(R.Cube)
                
                cube = ...
                    double(R.Cube);
                
                if isfield(R, 'Wavenumber') && ...
                        ~isempty(R.Wavenumber)
                    
                    wn = ...
                        double(R.Wavenumber(:));
                    
                else
                    
                    wn = ...
                        (1:size(cube, 3)).';
                end
                
                return
            end
            
            % =============================================================
            % LINKED CHECKPOINT
            % =============================================================
            
            absolutePath = '';
            relativePath = '';
            
            if isfield(R, 'SpectralDataPath')
                
                absolutePath = ...
                    char(string(R.SpectralDataPath));
            end
            
            if isfield(R, 'SpectralDataRelativePath')
                
                relativePath = ...
                    char(string(R.SpectralDataRelativePath));
            end
            
            linkedPath = ...
                app.resolveStoredPath( ...
                absolutePath, ...
                relativePath);
            
            if isempty(linkedPath)
                
                error( ...
                    ['The linked spectral cube cannot be resolved.' newline ...
                    'Absolute path: ' absolutePath newline ...
                    'Relative path: ' relativePath]);
            end
            
            [cube, wn] = ...
                app.loadCubeFromCheckpoint( ...
                linkedPath);
            
        end
        
        
        function S = hydrateLinkedStageForResume(app, S)
            
            % =============================================================
            % ARTIFACT V2
            % =============================================================
            
            if isfield(S, 'RamanDataArtifact') && ...
                    isstruct(S.RamanDataArtifact)
                
                R = ...
                    S.RamanDataArtifact;
                
                if ~isfield(R, 'Cube') || ...
                        isempty(R.Cube)
                    
                    absolutePath = '';
                    relativePath = '';
                    
                    if isfield(R, 'SpectralDataPath')
                        
                        absolutePath = ...
                            char(string(R.SpectralDataPath));
                    end
                    
                    if isfield(R, 'SpectralDataRelativePath')
                        
                        relativePath = ...
                            char(string(R.SpectralDataRelativePath));
                    end
                    
                    spectralPath = ...
                        app.resolveStoredPath( ...
                        absolutePath, ...
                        relativePath);
                    
                    if isempty(spectralPath)
                        
                        error( ...
                            'Artifact spectral source could not be resolved.');
                    end
                    
                    [cube, wn] = ...
                        app.loadCubeFromCheckpoint( ...
                        spectralPath);
                    
                    R.Cube = ...
                        cube;
                    
                    if ~isfield(R, 'Wavenumber') || ...
                            isempty(R.Wavenumber)
                        
                        R.Wavenumber = ...
                            wn;
                    end
                    
                    S.RamanDataArtifact = ...
                        R;
                end
            end
            
            % =============================================================
            % BACKGROUND V2
            % =============================================================
            
            if isfield(S, 'RamanDataBackground') && ...
                    isstruct(S.RamanDataBackground)
                
                R = ...
                    S.RamanDataBackground;
                
                if ~isfield(R, 'Cube') || ...
                        isempty(R.Cube)
                    
                    absolutePath = '';
                    relativePath = '';
                    
                    if isfield(R, 'SpectralDataPath')
                        
                        absolutePath = ...
                            char(string(R.SpectralDataPath));
                    end
                    
                    if isfield(R, 'SpectralDataRelativePath')
                        
                        relativePath = ...
                            char(string(R.SpectralDataRelativePath));
                    end
                    
                    spectralPath = ...
                        app.resolveStoredPath( ...
                        absolutePath, ...
                        relativePath);
                    
                    if isempty(spectralPath)
                        
                        error( ...
                            'Background spectral source could not be resolved.');
                    end
                    
                    [cube, wn] = ...
                        app.loadCubeFromCheckpoint( ...
                        spectralPath);
                    
                    R.Cube = ...
                        cube;
                    
                    if ~isfield(R, 'Wavenumber') || ...
                            isempty(R.Wavenumber)
                        
                        R.Wavenumber = ...
                            wn;
                    end
                    
                    S.RamanDataBackground = ...
                        R;
                end
            end
            
        end


        function savedPath = saveFinalBackgroundData(app)
            
            app.initializeProjectPaths();
            
            if isempty(app.Paths.BackgroundDataRoot)
                
                error( ...
                    'Background workspace is unavailable.');
            end
            
            if ~isfolder(app.Paths.BackgroundDataRoot)
                mkdir(app.Paths.BackgroundDataRoot);
            end
            
            if isempty(app.BackgroundInputCube) || ...
                    ndims(app.BackgroundInputCube) ~= 3
                
                error( ...
                    'BackgroundInputCube is unavailable.');
            end
            
            [ny, nx, nWn] = ...
                size(app.BackgroundInputCube);
            
            if numel(app.Wavenumber) ~= nWn
                
                error( ...
                    'Background wavenumber does not match active cube.');
            end
            
            scanID = ...
                app.getCurrentScanID();
            
            spectralDataPath = ...
                app.getCurrentSpectralDataPath();
            
            originalSourcePath = ...
                app.getOriginalSourceMatPath();
            
            savedPath = ...
                fullfile( ...
                app.Paths.BackgroundDataRoot, ...
                [scanID '_background_result.mat']);
            
            % =============================================================
            % LINKED RAMAN DATA
            %
            % NO CUBE IS STORED HERE.
            % =============================================================
            
            RamanDataBackground = struct();
            
            RamanDataBackground.SchemaVersion = ...
                2;
            
            RamanDataBackground.FormatVersion = ...
                'RamanCube Background Checkpoint v2';
            
            RamanDataBackground.StorageMode = ...
                'linked_spectral_cube';
            
            RamanDataBackground.Stage = ...
                'Background';
            
            RamanDataBackground.ScanName = ...
                app.CurrentScanName;
            
            RamanDataBackground.ScanID = ...
                scanID;
            
            RamanDataBackground.Wavenumber = ...
                double(app.Wavenumber(:));
            
            RamanDataBackground.CubeSize = ...
                [ny nx nWn];
            
            RamanDataBackground.SpectralDataPath = ...
                spectralDataPath;
            
            RamanDataBackground.SpectralDataRelativePath = ...
                app.makeProjectRelativePath( ...
                spectralDataPath);
            
            RamanDataBackground.OriginalSourceMatPath = ...
                originalSourcePath;
            
            RamanDataBackground.OriginalSourceRelativePath = ...
                app.makeProjectRelativePath( ...
                originalSourcePath);
            
            % =============================================================
            % MASKS
            % =============================================================
            
            CellMask2D = ...
                app.BackgroundCellMask2D;
            
            BackgroundMask2D = ...
                app.BackgroundBackgroundMask2D;
            
            UncertainMask2D = ...
                app.BackgroundUncertainMask2D;
            
            ValidPixelMask2D = ...
                app.BackgroundValidMask2D;
            
            ArtifactMask2D = ...
                app.BackgroundArtifactMask2D;
            
            ClusterLabelMap = ...
                app.BackgroundLabelMap;
            
            ConfidenceMap = ...
                app.BackgroundConfidenceMap;
            
            ManualClassMap = ...
                app.BackgroundManualClassMap;
            
            ClusterState = ...
                app.BackgroundClusters;
            
            % =============================================================
            % SCIENTIFIC QC
            % =============================================================
            
            GlobalClusterMetrics = ...
                app.BackgroundGlobalMetrics;
            
            ClusterMetrics = ...
                app.BackgroundClusterMetrics;
            
            SilhouetteValues = ...
                app.BackgroundSilhouetteValues;
            
            PixelStability = ...
                app.BackgroundPixelStability;
            
            MetricPixelIdx = ...
                app.BackgroundMetricPixelIdx;
            
            MetricLabels = ...
                app.BackgroundMetricLabels;
            
            % Retained because these are needed to reproduce/edit
            % Background clustering without rerunning PCA from scratch.
            PCAScores = ...
                app.BackgroundAllScores;
            
            PCACoefficients = ...
                app.BackgroundPCAcoeff;
            
            PCAMean = ...
                app.BackgroundPCAmu;
            
            ClusterCentroids = ...
                app.BackgroundCentroids;
            
            TrainingLocalIdx = ...
                app.BackgroundTrainingLocalIdx;
            
            TrainingLinearIdx = ...
                app.BackgroundTrainingLinearIdx;
            
            LastSplitTrial = ...
                app.BackgroundLastSplitTrial;
            
            % =============================================================
            % PARAMETERS
            % =============================================================
            
            SegmentationParams = struct();
            
            SegmentationParams.Method = ...
                app.BackgroundMethod;
            
            SegmentationParams.Morphology = ...
                app.BackgroundMorphology;
            
            SegmentationParams.K = ...
                app.BackgroundK;
            
            SegmentationParams.VarianceThreshold = ...
                app.BackgroundVarianceThreshold;
            
            SegmentationParams.MaxPC = ...
                app.BackgroundMaxPC;
            
            SegmentationParams.NumPCUsed = ...
                app.BackgroundNumPCUsed;
            
            SegmentationParams.ExplainedVariance = ...
                app.BackgroundExplained;
            
            SegmentationParams.Replicates = ...
                app.BackgroundReplicates;
            
            SegmentationParams.RandomSeed = ...
                app.BackgroundRandomSeed;
            
            SegmentationParams.TrainingMaxSpectra = ...
                app.BackgroundTrainingMaxSpectra;
            
            % =============================================================
            % PROCESSING INFO
            % =============================================================
            
            ProcessingInfo = struct();
            
            ProcessingInfo.SchemaVersion = ...
                2;
            
            ProcessingInfo.ScanName = ...
                app.CurrentScanName;
            
            ProcessingInfo.ScanID = ...
                scanID;
            
            ProcessingInfo.OriginalMatPath = ...
                originalSourcePath;
            
            ProcessingInfo.SpectralDataPath = ...
                spectralDataPath;
            
            ProcessingInfo.ProcessedDate = ...
                datestr(now, 'yyyy-mm-dd HH:MM:SS');
            
            ProcessingInfo.SoftwareStep = ...
                'scanpretreat background removal';
            
            ProcessingInfo.InputStage = ...
                'artifact-confirmed linked spectral cube';
            
            ProcessingInfo.OutputFolder = ...
                app.Paths.BackgroundDataRoot;
            
            ProcessingInfo.OutputMatPath = ...
                savedPath;
            
            ProcessingInfo.StorageMode = ...
                'linked_spectral_cube';
            
            ProcessingInfo.Note = ...
                ['No Raman cube is duplicated at Background stage. ' ...
                'Segmentation state references the materialized Cosmic cube.'];
            
            % =============================================================
            % ATOMIC SAVE
            % =============================================================
            
            tempPath = ...
                [savedPath '.tmp.mat'];
            
            save( ...
                tempPath, ...
                'RamanDataBackground', ...
                'CellMask2D', ...
                'BackgroundMask2D', ...
                'UncertainMask2D', ...
                'ValidPixelMask2D', ...
                'ArtifactMask2D', ...
                'ClusterLabelMap', ...
                'ConfidenceMap', ...
                'ManualClassMap', ...
                'ClusterState', ...
                'GlobalClusterMetrics', ...
                'ClusterMetrics', ...
                'SilhouetteValues', ...
                'PixelStability', ...
                'MetricPixelIdx', ...
                'MetricLabels', ...
                'PCAScores', ...
                'PCACoefficients', ...
                'PCAMean', ...
                'ClusterCentroids', ...
                'TrainingLocalIdx', ...
                'TrainingLinearIdx', ...
                'LastSplitTrial', ...
                'SegmentationParams', ...
                'ProcessingInfo', ...
                '-v7.3');
            
            if isfile(savedPath)
                delete(savedPath);
            end
            
            [ok, messageText] = ...
                movefile( ...
                tempPath, ...
                savedPath, ...
                'f');
            
            if ~ok
                
                error( ...
                    'Could not commit Background checkpoint:%s%s', ...
                    newline, ...
                    messageText);
            end
            
            app.BackgroundLastSavedPath = ...
                savedPath;
            
            % =============================================================
            % MANIFEST
            % =============================================================
            
            app.updateCurrentManifestStage( ...
                'Background', ...
                savedPath, ...
                savedPath, ...
                '', ...
                'linked_spectral_cube', ...
                spectralDataPath);
            
        end
        
        function showScanspectraParent(app)
            
            if isempty(app.mainApp)
                
                uialert( ...
                    app.UIFigure, ...
                    ['The scanspectra parent app is unavailable.' newline ...
                    'The result has already been saved.'], ...
                    'Parent app unavailable');
                
                return
            end
            
            try
                if ~isvalid(app.mainApp)
                    
                    uialert( ...
                        app.UIFigure, ...
                        ['The scanspectra parent app is no longer valid.' ...
                        newline ...
                        'The result has already been saved.'], ...
                        'Parent app unavailable');
                    
                    return
                end
                
                app.mainApp.UIFigure.Visible = 'on';
                
                try
                    figure(app.mainApp.UIFigure);
                catch
                end
                
                try
                    if ismethod(app.mainApp, 'addHistory')
                        
                        app.mainApp.addHistory(sprintf( ...
                            'Returned from scanpretreat: %s', ...
                            app.CurrentScanName));
                    end
                catch
                end
                
                app.UIFigure.Visible = 'off';
                
            catch ME
                uialert( ...
                    app.UIFigure, ...
                    ME.message, ...
                    'Return to scanspectra error');
            end
        end
        
        function showMainHomeFromPretreat(app)
            
            if isempty(app.mainApp)
                
                uialert( ...
                    app.UIFigure, ...
                    ['The scanspectra parent app is unavailable.' newline ...
                    'The result has already been saved.'], ...
                    'Home unavailable');
                
                return
            end
            
            try
                if ~isvalid(app.mainApp)
                    
                    uialert( ...
                        app.UIFigure, ...
                        ['The scanspectra parent app is no longer valid.' ...
                        newline ...
                        'The result has already been saved.'], ...
                        'Home unavailable');
                    
                    return
                end
                
                if ismethod( ...
                        app.mainApp, ...
                        'returnToMainHomeFromPretreat')
                    
                    app.mainApp.returnToMainHomeFromPretreat();
                    
                    app.UIFigure.Visible = 'off';
                    
                else
                    uialert( ...
                        app.UIFigure, ...
                        ['scanspectra does not yet contain:' newline ...
                        'returnToMainHomeFromPretreat().'], ...
                        'Method missing');
                end
                
            catch ME
                uialert( ...
                    app.UIFigure, ...
                    ME.message, ...
                    'Return Home error');
            end
        end
        
        function closePretreatApp(app)
            
            if ~isempty(app.mainApp)
                
                try
                    if isvalid(app.mainApp) && ...
                            isprop(app.mainApp, 'UIFigure') && ...
                            isvalid(app.mainApp.UIFigure)
                        
                        app.mainApp.UIFigure.Visible = 'on';
                    end
                catch
                end
            end
            
            delete(app);
        end
        
        function resetArtifactForRerun(app)
            
            app.ArtifactReady = false;
            app.ArtifactConfirmed = false;
            
            app.ArtifactMask2D = logical.empty;
            app.ValidPixelMask2D = logical.empty;
            
            app.ArtifactDisplayMapRaw = [];
            app.ArtifactDisplayMapClean = [];
            app.ArtifactDisplayLabel = '';
            
            app.ArtifactStripeScoreMap = [];
            app.ArtifactBandConsistencyMap = [];
            app.ArtifactSpectralZMap = [];
            
            app.ArtifactStrongSeedMask = logical.empty;
            app.ArtifactWeakSupportMask = logical.empty;
            app.ArtifactHysteresisMask = logical.empty;
            app.ArtifactRowBandMask = logical.empty;
            
            app.ArtifactRowEvidence = struct();
            app.ArtifactTable = table();
            
            app.ArtifactBandMaps = [];
            app.ArtifactBandLabels = {};
            app.ArtifactZStack = [];
            
            app.ArtifactDisplayInpaintCube = [];
            app.ArtifactDisplayInpaintInfo = struct();
            
            app.ArtifactParams = ...
                app.getArtifactPresetParams( ...
                app.ModeDropDown.Value);
            
            app.plotArtifactInitialView();
            
            app.TabGroup.SelectedTab = ...
                app.ArtifactremovalTab;
            
            app.setArtifactText({ ...
                'ARTIFACT REMOVAL RESET'; ...
                '================================'; ...
                ''; ...
                ['Scan: ' app.CurrentScanName]; ...
                ''; ...
                'The saved Cosmic result remains active.'; ...
                'Artifact detection may now be repeated.'});
        end
        
        function resetBackgroundForRerun(app)
            
            if isempty(app.ValidPixelMask2D) || ...
                    isempty(app.ArtifactMask2D)
                
                uialert( ...
                    app.UIFigure, ...
                    ['No confirmed Artifact result is available.' newline ...
                    'Finish Artifact removal first.'], ...
                    'Artifact result missing');
                
                return
            end
            
            app.prepareBackgroundFromArtifact();
            
            app.BackgroundValidated = false;
            app.BackgroundConfirmed = false;
            
            app.TabGroup.SelectedTab = ...
                app.BackgroundremovalTab;
            
            app.TextArea_3.Value = { ...
                'BACKGROUND REMOVAL RESET'; ...
                '================================'; ...
                ''; ...
                ['Scan: ' app.CurrentScanName]; ...
                sprintf('Valid pixels received: %d', ...
                nnz(app.BackgroundValidMask2D)); ...
                ''; ...
                'The Artifact result remains active.'; ...
                'Run Background segmentation again.'};
            
            app.TextArea_4.Value = { ...
                'QC PARAMETERS'; ...
                '================================'; ...
                ''; ...
                'No current Background segmentation.'};
        end
        
        function returnBackgroundToSingleExtraction(app, savedPath)
            
            if nargin < 2
                savedPath = app.BackgroundLastSavedPath;
            end
            
            if isempty(app.mainApp)
                
                uialert( ...
                    app.UIFigure, ...
                    ['scanspectra is unavailable.' newline ...
                    'The Background result was still saved.'], ...
                    'Parent app unavailable');
                
                return
            end
            
            try
                if ~isvalid(app.mainApp)
                    
                    uialert( ...
                        app.UIFigure, ...
                        ['scanspectra is no longer valid.' newline ...
                        'The Background result was still saved.'], ...
                        'Parent app unavailable');
                    
                    return
                end
                
                if ~ismethod( ...
                        app.mainApp, ...
                        'receiveBackgroundAndOpenSingleExtraction')
                    
                    uialert( ...
                        app.UIFigure, ...
                        ['scanspectra is missing method:' newline ...
                        'receiveBackgroundAndOpenSingleExtraction().'], ...
                        'Method missing');
                    
                    return
                end
                
                % =====================================================
                % CHOOSE CELL MASK USED DOWNSTREAM
                %
                % Current implementation has one accepted Cell mask.
                % Uncertain pixels remain excluded.
                % =====================================================
                
                extractionMask = ...
                    logical(app.BackgroundCellMask2D) & ...
                    logical(app.BackgroundValidMask2D);
                
                if ~any(extractionMask(:))
                    
                    uialert( ...
                        app.UIFigure, ...
                        'The final Cell mask is empty.', ...
                        'Cannot open extraction');
                    
                    return
                end
                
                payload = struct();
                
                payload.SourceApp = ...
                    'scanpretreat';
                
                payload.SourceAction = ...
                    'Background removal finished';
                
                payload.Stage = ...
                    'Background-confirmed cell scan';
                
                payload.ScanName = ...
                    string(app.CurrentScanName);
                
                payload.ScanID = ...
                    string(app.getCurrentScanID());
                
                payload.Cube = ...
                    double(app.BackgroundInputCube);
                
                payload.Wavenumber = ...
                    double(app.Wavenumber(:));
                
                % Critical:
                % single_scan_extract will sample/extract only cell pixels.
                payload.ValidMask = ...
                    extractionMask;
                
                payload.CellMask2D = ...
                    logical(app.BackgroundCellMask2D);
                
                payload.BackgroundMask2D = ...
                    logical(app.BackgroundBackgroundMask2D);
                
                payload.UncertainMask2D = ...
                    logical(app.BackgroundUncertainMask2D);
                
                payload.ArtifactMask2D = ...
                    logical(app.BackgroundArtifactMask2D);
                
                payload.ArtifactValidMask2D = ...
                    logical(app.BackgroundValidMask2D);
                
                payload.SourceMatPath = ...
                    string(savedPath);
                
                payload.MaskSource = ...
                    'BackgroundCellMask2D';
                
                payload.CellPixels = ...
                    nnz(extractionMask);
                
                payload.BackgroundPixels = ...
                    nnz(app.BackgroundBackgroundMask2D);
                
                payload.UncertainPixels = ...
                    nnz(app.BackgroundUncertainMask2D);
                
                payload.CreatedDate = ...
                    string(datetime( ...
                    'now', ...
                    'Format', ...
                    'yyyy-MM-dd HH:mm:ss'));
                
                app.mainApp.receiveBackgroundAndOpenSingleExtraction( ...
                    payload);
                
                app.UIFigure.Visible = 'off';
                
            catch ME
                uialert( ...
                    app.UIFigure, ...
                    ME.message, ...
                    'Open single extraction error');
            end
        end
        
        
        function [Xnorm, pixelIdx] = getBackgroundMetricSpectra(app)
            
            Xnorm = [];
            pixelIdx = [];
            
            if isempty(app.BackgroundInputCube) || ...
                    isempty(app.BackgroundValidMask2D)
                
                return
            end
            
            cube = double(app.BackgroundInputCube);
            
            [ny, nx, nWn] = size(cube);
            
            pixelIdx = find(app.BackgroundValidMask2D);
            
            if isempty(pixelIdx)
                return
            end
            
            Xall = reshape(cube, ny * nx, nWn);
            Xnorm = Xall(pixelIdx, :);
            
            good = all(isfinite(Xnorm), 2);
            
            Xnorm = Xnorm(good, :);
            pixelIdx = pixelIdx(good);
            
            if isempty(Xnorm)
                return
            end
            
            % Same normalization used by the current PCA + K-means.
            rowMedian = median(Xnorm, 2, 'omitnan');
            Xnorm = Xnorm - rowMedian;
            
            rowNorm = sqrt(sum(Xnorm .^ 2, 2));
            rowNorm(rowNorm <= eps | ~isfinite(rowNorm)) = 1;
            
            Xnorm = Xnorm ./ rowNorm;
            Xnorm(~isfinite(Xnorm)) = 0;
        end
        
        
        function labels = getBackgroundActiveLabelsForPixels( ...
                app, pixelIdx)
            
            labels = zeros(numel(pixelIdx), 1);
            
            if isempty(pixelIdx) || ...
                    isempty(app.BackgroundClusters)
                
                return
            end
            
            [ny, nx, ~] = size(app.BackgroundInputCube);
            
            inverseMap = zeros(ny * nx, 1);
            inverseMap(pixelIdx) = 1:numel(pixelIdx);
            
            activeMask = [app.BackgroundClusters.Active];
            
            for i = find(activeMask)
                
                C = app.BackgroundClusters(i);
                
                pix = C.PixelIdx(:);
                
                pix = pix( ...
                    pix >= 1 & ...
                    pix <= ny * nx);
                
                localRows = inverseMap(pix);
                localRows = localRows(localRows > 0);
                
                labels(localRows) = C.ID;
            end
        end

        function computeBackgroundScientificMetrics(app)
            
            % =========================================================
            % RESET OLD METRICS
            % =========================================================
            
            app.BackgroundGlobalMetrics = struct();
            
            app.BackgroundClusterMetrics = struct( ...
                'ClusterID', {}, ...
                'ClusterName', {}, ...
                'PixelCount', {}, ...
                'PixelFraction', {}, ...
                'MeanSilhouette', {}, ...
                'MedianSilhouette', {}, ...
                'P10Silhouette', {}, ...
                'NegativeSilhouetteFraction', {}, ...
                'MedianConfidence', {}, ...
                'LowConfidenceFraction', {}, ...
                'MedianDistance', {}, ...
                'P90Distance', {}, ...
                'NormalizedDispersion', {}, ...
                'MedianSpectralAngle', {}, ...
                'P90SpectralAngle', {}, ...
                'Stability', {}, ...
                'ConnectedComponents', {}, ...
                'LargestComponentFraction', {}, ...
                'IsolatedPixelFraction', {}, ...
                'SplitPriority', {}, ...
                'SplitLevel', {}, ...
                'Recommendation', {}, ...
                'Reasons', {});
            
            app.BackgroundSilhouetteValues = [];
            app.BackgroundPixelStability = [];
            
            % =========================================================
            % PREPARE SPECTRA AND ACTIVE LABELS
            % =========================================================
            
            [Xnorm, pixelIdx] = ...
                app.getBackgroundMetricSpectra();
            
            if isempty(Xnorm) || isempty(pixelIdx)
                return
            end
            
            labels = ...
                app.getBackgroundActiveLabelsForPixels(pixelIdx);
            
            assigned = labels > 0;
            
            Xnorm = Xnorm(assigned, :);
            pixelIdx = pixelIdx(assigned);
            labels = labels(assigned);
            
            activeIDs = unique(labels, 'stable');
            
            if numel(activeIDs) < 2 || ...
                    size(Xnorm, 1) < 10
                
                return
            end
            
            % =========================================================
            % BUILD OR REBUILD PCA SCORE SPACE
            % =========================================================
            
            nPC = app.BackgroundNumPCUsed;
            
            if isempty(app.BackgroundPCAcoeff) || ...
                    isempty(app.BackgroundPCAmu) || ...
                    nPC < 1
                
                [coeff, scores, ~, ~, explained, mu] = ...
                    pca( ...
                    Xnorm, ...
                    'Centered', true, ...
                    'Economy', true);
                
                cumulative = cumsum(explained);
                
                nPC = find( ...
                    cumulative >= app.BackgroundVarianceThreshold, ...
                    1, ...
                    'first');
                
                if isempty(nPC)
                    nPC = size(coeff, 2);
                end
                
                nPC = min([ ...
                    nPC, ...
                    app.BackgroundMaxPC, ...
                    size(coeff, 2)]);
                
                nPC = max(1, nPC);
                
                app.BackgroundPCAcoeff = coeff;
                app.BackgroundPCAmu = mu;
                app.BackgroundExplained = explained;
                app.BackgroundNumPCUsed = nPC;
                
                scores = scores(:, 1:nPC);
                
            else
                nPC = min(nPC, size(app.BackgroundPCAcoeff, 2));
                
                scores = ...
                    (Xnorm - app.BackgroundPCAmu) * ...
                    app.BackgroundPCAcoeff(:, 1:nPC);
            end
            
            app.BackgroundAllScores = scores;
            app.BackgroundMetricLabels = labels;
            app.BackgroundMetricPixelIdx = pixelIdx;
            
            % =========================================================
            % SAMPLE FOR EXPENSIVE METRICS
            %
            % silhouette is O(N^2), therefore use a reproducible sample.
            % =========================================================
            
            rng(app.BackgroundMetricRandomSeed);
            
            nMetric = size(scores, 1);
            
            maxSil = min( ...
                app.BackgroundSilhouetteMaxSpectra, ...
                nMetric);
            
            if nMetric > maxSil
                
                silRows = app.stratifiedBackgroundMetricSample( ...
                    labels, ...
                    maxSil);
                
            else
                silRows = (1:nMetric)';
            end
            
            scoreSil = scores(silRows, :);
            labelSil = labels(silRows);
            
            % =========================================================
            % SILHOUETTE
            % =========================================================
            
            silhouetteSample = nan(numel(silRows), 1);
            
            try
                silhouetteSample = ...
                    silhouette( ...
                    scoreSil, ...
                    labelSil, ...
                    'sqeuclidean');
            catch
                silhouetteSample(:) = NaN;
            end
            
            silhouetteAll = nan(nMetric, 1);
            silhouetteAll(silRows) = silhouetteSample;
            
            app.BackgroundSilhouetteValues = silhouetteAll;
            
            % =========================================================
            % GLOBAL CLUSTERING INDICES
            % =========================================================
            
            globalMetrics = struct();
            
            validSil = silhouetteSample(isfinite(silhouetteSample));
            
            if isempty(validSil)
                globalMetrics.MicroSilhouette = NaN;
            else
                globalMetrics.MicroSilhouette = ...
                    mean(validSil, 'omitnan');
            end
            
            clusterMeanSil = nan(numel(activeIDs), 1);
            
            for i = 1:numel(activeIDs)
                
                vals = silhouetteSample( ...
                    labelSil == activeIDs(i));
                
                vals = vals(isfinite(vals));
                
                if ~isempty(vals)
                    clusterMeanSil(i) = ...
                        mean(vals, 'omitnan');
                end
            end
            
            globalMetrics.MacroSilhouette = ...
                mean(clusterMeanSil, 'omitnan');
            
            try
                evaDB = evalclusters( ...
                    scoreSil, ...
                    labelSil, ...
                    'DaviesBouldin');
                
                globalMetrics.DaviesBouldin = ...
                    evaDB.CriterionValues;
            catch
                globalMetrics.DaviesBouldin = NaN;
            end
            
            try
                evaCH = evalclusters( ...
                    scoreSil, ...
                    labelSil, ...
                    'CalinskiHarabasz');
                
                globalMetrics.CalinskiHarabasz = ...
                    evaCH.CriterionValues;
            catch
                globalMetrics.CalinskiHarabasz = NaN;
            end
            
            % =========================================================
            % REPEATED-RUN PIXEL STABILITY
            % =========================================================
            
            pixelStability = ...
                app.computeBackgroundPixelStability( ...
                scoreSil, ...
                labelSil, ...
                activeIDs);
            
            stabilityAll = nan(nMetric, 1);
            stabilityAll(silRows) = pixelStability;
            
            app.BackgroundPixelStability = stabilityAll;
            
            globalMetrics.MeanStability = ...
                mean(pixelStability, 'omitnan');
            
            globalMetrics.UnstableFraction = ...
                mean(pixelStability < 0.80, 'omitnan');
            
            globalMetrics.NumberOfClusters = ...
                numel(activeIDs);
            
            globalMetrics.MetricPixels = ...
                nMetric;
            
            globalMetrics.SilhouetteSamplePixels = ...
                numel(silRows);
            
            globalMetrics.NumPCUsed = ...
                nPC;
            
            globalMetrics.ExplainedVarianceUsed = ...
                app.getBackgroundExplainedUsed();
            
            % =========================================================
            % DISTANCES TO CURRENT ACTIVE CENTROIDS
            % =========================================================
            
            centroidMatrix = zeros(numel(activeIDs), size(scores, 2));
            
            for i = 1:numel(activeIDs)
                centroidMatrix(i, :) = ...
                    mean( ...
                    scores(labels == activeIDs(i), :), ...
                    1, ...
                    'omitnan');
            end
            
            allDistance = nan(nMetric, 1);
            
            for i = 1:numel(activeIDs)
                
                rows = labels == activeIDs(i);
                
                delta = ...
                    scores(rows, :) - centroidMatrix(i, :);
                
                allDistance(rows) = ...
                    sqrt(sum(delta .^ 2, 2));
            end
            
            globalMedianDistance = ...
                median(allDistance, 'omitnan');
            
            if ~isfinite(globalMedianDistance) || ...
                    globalMedianDistance <= eps
                
                globalMedianDistance = 1;
            end
            
            % =========================================================
            % CONFIDENCE VALUES
            % =========================================================
            
            confidenceVector = nan(nMetric, 1);
            
            if ~isempty(app.BackgroundConfidenceMap)
                
                confidenceVector = ...
                    app.BackgroundConfidenceMap(pixelIdx);
                
                confidenceVector = ...
                    confidenceVector(:);
            end
            
            % =========================================================
            % PER-CLUSTER METRICS
            % =========================================================
            
            [ny, nx, ~] = size(app.BackgroundInputCube);
            
            metrics = app.BackgroundClusterMetrics;
            
            for i = 1:numel(activeIDs)
                
                id = activeIDs(i);
                
                structIdx = ...
                    app.findBackgroundClusterIndex(id);
                
                if isempty(structIdx)
                    continue
                end
                
                C = app.BackgroundClusters(structIdx);
                
                rows = labels == id;
                nCluster = nnz(rows);
                
                silVals = silhouetteAll(rows);
                silVals = silVals(isfinite(silVals));
                
                confVals = confidenceVector(rows);
                confVals = confVals(isfinite(confVals));
                
                distVals = allDistance(rows);
                distVals = distVals(isfinite(distVals));
                
                stabilityVals = stabilityAll(rows);
                stabilityVals = ...
                    stabilityVals(isfinite(stabilityVals));
                
                % -----------------------------------------------------
                % Spectral angle to cluster mean spectrum
                % -----------------------------------------------------
                
                Xcluster = Xnorm(rows, :);
                
                meanSpecNorm = ...
                    mean(Xcluster, 1, 'omitnan');
                
                meanNorm = norm(meanSpecNorm);
                
                rowNorm = sqrt(sum(Xcluster .^ 2, 2));
                
                denom = rowNorm * max(meanNorm, eps);
                
                cosineValue = ...
                    (Xcluster * meanSpecNorm(:)) ./ ...
                    max(denom, eps);
                
                cosineValue = max(-1, min(1, cosineValue));
                
                spectralAngles = acos(cosineValue);
                
                % -----------------------------------------------------
                % Spatial coherence
                % -----------------------------------------------------
                
                clusterMask = false(ny, nx);
                clusterMask(C.PixelIdx) = true;
                
                CC = bwconncomp(clusterMask, 8);
                
                componentSizes = ...
                    cellfun(@numel, CC.PixelIdxList);
                
                if isempty(componentSizes)
                    
                    nComponents = 0;
                    largestFraction = 0;
                    isolatedFraction = 0;
                    
                else
                    
                    nComponents = numel(componentSizes);
                    
                    largestFraction = ...
                        max(componentSizes) / ...
                        max(sum(componentSizes), 1);
                    
                    % Single pixels and tiny 2-pixel fragments.
                    isolatedFraction = ...
                        sum(componentSizes(componentSizes <= 2)) / ...
                        max(sum(componentSizes), 1);
                end
                
                M = struct();
                
                M.ClusterID = id;
                M.ClusterName = C.Name;
                M.PixelCount = nCluster;
                M.PixelFraction = nCluster / nMetric;
                
                M.MeanSilhouette = ...
                    app.safeMetricMean(silVals);
                
                M.MedianSilhouette = ...
                    app.safeMetricPercentile(silVals, 50);
                
                M.P10Silhouette = ...
                    app.safeMetricPercentile(silVals, 10);
                
                if isempty(silVals)
                    M.NegativeSilhouetteFraction = NaN;
                else
                    M.NegativeSilhouetteFraction = ...
                        mean(silVals < 0);
                end
                
                M.MedianConfidence = ...
                    app.safeMetricPercentile(confVals, 50);
                
                if isempty(confVals)
                    M.LowConfidenceFraction = NaN;
                else
                    M.LowConfidenceFraction = ...
                        mean(confVals < 0.15);
                end
                
                M.MedianDistance = ...
                    app.safeMetricPercentile(distVals, 50);
                
                M.P90Distance = ...
                    app.safeMetricPercentile(distVals, 90);
                
                M.NormalizedDispersion = ...
                    M.MedianDistance / globalMedianDistance;
                
                M.MedianSpectralAngle = ...
                    app.safeMetricPercentile( ...
                    spectralAngles, 50);
                
                M.P90SpectralAngle = ...
                    app.safeMetricPercentile( ...
                    spectralAngles, 90);
                
                M.Stability = ...
                    app.safeMetricMean(stabilityVals);
                
                M.ConnectedComponents = ...
                    nComponents;
                
                M.LargestComponentFraction = ...
                    largestFraction;
                
                M.IsolatedPixelFraction = ...
                    isolatedFraction;
                
                [ ...
                    M.SplitPriority, ...
                    M.SplitLevel, ...
                    M.Recommendation, ...
                    M.Reasons] = ...
                    app.calculateBackgroundSplitPriority(M);
                
                metrics(end + 1) = M; %#ok<AGROW>
                
                app.BackgroundClusters(structIdx).Metrics = M;
            end
            
            app.BackgroundClusterMetrics = metrics;
            app.BackgroundGlobalMetrics = globalMetrics;
            app.BackgroundCentroids = centroidMatrix;
        end
        
        
        function rows = stratifiedBackgroundMetricSample( ...
                app, labels, maxCount)
            
            labels = labels(:);
            
            ids = unique(labels, 'stable');
            
            rows = [];
            
            perCluster = ...
                max(10, floor(maxCount / numel(ids)));
            
            rng(app.BackgroundMetricRandomSeed);
            
            for i = 1:numel(ids)
                
                idx = find(labels == ids(i));
                
                takeN = min(numel(idx), perCluster);
                
                if numel(idx) > takeN
                    idx = idx(randperm(numel(idx), takeN));
                end
                
                rows = [rows; idx(:)]; %#ok<AGROW>
            end
            
            rows = unique(rows, 'stable');
            
            if numel(rows) > maxCount
                rows = rows( ...
                    randperm(numel(rows), maxCount));
            end
            
            rows = rows(:);
        end
        
        
        function stability = computeBackgroundPixelStability( ...
                app, scores, referenceLabels, activeIDs)
            
            n = size(scores, 1);
            
            stabilityCount = zeros(n, 1);
            
            nRuns = max(3, round(app.BackgroundStabilityRuns));
            K = numel(activeIDs);
            
            if K < 2 || n < K
                stability = nan(n, 1);
                return
            end
            
            for runID = 1:nRuns
                
                rng( ...
                    app.BackgroundMetricRandomSeed + runID);
                
                try
                    trialLabels = ...
                        kmeans( ...
                        scores, ...
                        K, ...
                        'Replicates', 1, ...
                        'MaxIter', 300, ...
                        'Distance', 'sqeuclidean', ...
                        'EmptyAction', 'singleton');
                    
                catch
                    continue
                end
                
                mappedLabels = ...
                    app.matchTrialLabelsByOverlap( ...
                    trialLabels, ...
                    referenceLabels, ...
                    activeIDs);
                
                stabilityCount = ...
                    stabilityCount + ...
                    double(mappedLabels == referenceLabels);
            end
            
            stability = ...
                stabilityCount / nRuns;
        end
        
        
        function mapped = matchTrialLabelsByOverlap( ...
                app, trialLabels, referenceLabels, activeIDs)
            
            trialIDs = unique(trialLabels, 'stable');
            
            overlap = zeros( ...
                numel(trialIDs), ...
                numel(activeIDs));
            
            for i = 1:numel(trialIDs)
                
                for j = 1:numel(activeIDs)
                    
                    overlap(i, j) = nnz( ...
                        trialLabels == trialIDs(i) & ...
                        referenceLabels == activeIDs(j));
                end
            end
            
            mapped = zeros(size(trialLabels));
            
            usedReference = false(numel(activeIDs), 1);
            
            % Greedy maximum-overlap matching.
            while any(overlap(:) > 0)
                
                [~, linearIndex] = max(overlap(:));
                
                [i, j] = ind2sub( ...
                    size(overlap), ...
                    linearIndex);
                
                mapped(trialLabels == trialIDs(i)) = ...
                    activeIDs(j);
                
                overlap(i, :) = -Inf;
                overlap(:, j) = -Inf;
                
                usedReference(j) = true;
            end
            
            % Fallback for unmatched trial clusters.
            for i = 1:numel(trialIDs)
                
                rows = trialLabels == trialIDs(i);
                
                if any(mapped(rows) == 0)
                    
                    refValues = referenceLabels(rows);
                    
                    if isempty(refValues)
                        continue
                    end
                    
                    mapped(rows) = mode(refValues);
                end
            end
        end
        
        
        function value = safeMetricMean(app, values)
            
            values = double(values(:));
            values = values(isfinite(values));
            
            if isempty(values)
                value = NaN;
            else
                value = mean(values, 'omitnan');
            end
        end
        
        
        function value = safeMetricPercentile( ...
                app, values, percentileValue)
            
            values = double(values(:));
            values = values(isfinite(values));
            
            if isempty(values)
                value = NaN;
            else
                value = prctile(values, percentileValue);
            end
        end
        
        
        function [score, level, recommendation, reasons] = ...
                calculateBackgroundSplitPriority(app, M)
            
            reasons = {};
            
            % =========================================================
            % 1. SILHOUETTE RISK
            % =========================================================
            
            if isfinite(M.MeanSilhouette)
                
                silhouetteRisk = ...
                    min(max( ...
                    (0.50 - M.MeanSilhouette) / 0.50, ...
                    0), 1);
                
            else
                silhouetteRisk = 0.50;
            end
            
            negativeRisk = ...
                app.metricRiskFromFraction( ...
                M.NegativeSilhouetteFraction, ...
                0.05, ...
                0.25);
            
            % =========================================================
            % 2. CONFIDENCE RISK
            % =========================================================
            
            confidenceRisk = ...
                app.metricRiskFromFraction( ...
                M.LowConfidenceFraction, ...
                0.05, ...
                0.30);
            
            % =========================================================
            % 3. DISPERSION RISK
            % =========================================================
            
            if isfinite(M.NormalizedDispersion)
                
                dispersionRisk = ...
                    min(max( ...
                    (M.NormalizedDispersion - 0.8) / 1.2, ...
                    0), 1);
                
            else
                dispersionRisk = 0.50;
            end
            
            % =========================================================
            % 4. STABILITY RISK
            % =========================================================
            
            if isfinite(M.Stability)
                stabilityRisk = 1 - M.Stability;
            else
                stabilityRisk = 0.50;
            end
            
            % =========================================================
            % 5. SPATIAL RISK
            % =========================================================
            
            spatialRisk = ...
                min(max(M.IsolatedPixelFraction / 0.20, 0), 1);
            
            % Dense small-cell mode:
            % many connected components are acceptable.
            % Therefore only tiny isolated fragments are penalized.
            if strcmp( ...
                    app.BackgroundMorphology, ...
                    'Large isolated cells')
                
                if M.LargestComponentFraction < 0.50
                    
                    spatialRisk = max( ...
                        spatialRisk, ...
                        1 - M.LargestComponentFraction);
                end
            end
            
            % =========================================================
            % AUDITABLE COMPOSITE SCORE
            % =========================================================
            
            score = ...
                0.20 * silhouetteRisk + ...
                0.15 * negativeRisk + ...
                0.20 * confidenceRisk + ...
                0.20 * dispersionRisk + ...
                0.20 * stabilityRisk + ...
                0.05 * spatialRisk;
            
            score = min(max(score, 0), 1);
            
            % =========================================================
            % EXPLANATION
            % =========================================================
            
            if isfinite(M.MeanSilhouette) && ...
                    M.MeanSilhouette < 0.25
                
                reasons{end + 1} = sprintf( ...
                    'Low mean silhouette: %.3f', ...
                    M.MeanSilhouette);
            end
            
            if isfinite(M.NegativeSilhouetteFraction) && ...
                    M.NegativeSilhouetteFraction > 0.15
                
                reasons{end + 1} = sprintf( ...
                    'Negative silhouette pixels: %.1f%%', ...
                    100 * M.NegativeSilhouetteFraction);
            end
            
            if isfinite(M.LowConfidenceFraction) && ...
                    M.LowConfidenceFraction > 0.20
                
                reasons{end + 1} = sprintf( ...
                    'Low-confidence pixels: %.1f%%', ...
                    100 * M.LowConfidenceFraction);
            end
            
            if isfinite(M.NormalizedDispersion) && ...
                    M.NormalizedDispersion > 1.50
                
                reasons{end + 1} = sprintf( ...
                    'Dispersion: %.2fx global median', ...
                    M.NormalizedDispersion);
            end
            
            if isfinite(M.Stability) && ...
                    M.Stability < 0.80
                
                reasons{end + 1} = sprintf( ...
                    'Repeated-run stability: %.3f', ...
                    M.Stability);
            end
            
            if M.IsolatedPixelFraction > 0.10
                
                reasons{end + 1} = sprintf( ...
                    'Tiny isolated fragments: %.1f%%', ...
                    100 * M.IsolatedPixelFraction);
            end
            
            if isempty(reasons)
                reasons = { ...
                    'No strong internal evidence requiring further split.'};
            end
            
            % =========================================================
            % LEVEL
            % =========================================================
            
            if score >= 0.60
                
                level = 'HIGH';
                recommendation = ...
                    'Trial split is recommended.';
                
            elseif score >= 0.30
                
                level = 'REVIEW';
                recommendation = ...
                    'Inspect spectra and run a trial split.';
                
            else
                
                level = 'LOW';
                recommendation = ...
                    'Further split is not currently supported.';
            end
        end
        
        
        function risk = metricRiskFromFraction( ...
                app, value, lowThreshold, highThreshold)
            
            if ~isfinite(value)
                risk = 0.50;
                return
            end
            
            risk = ...
                (value - lowThreshold) / ...
                max(highThreshold - lowThreshold, eps);
            
            risk = min(max(risk, 0), 1);
        end
        
        
        function trial = evaluateBackgroundTrialSplit( ...
                app, parentID, childK)
            
            trial = struct();
            
            parentIdx = ...
                app.findBackgroundClusterIndex(parentID);
            
            if isempty(parentIdx)
                error('Parent cluster was not found.');
            end
            
            parentPix = ...
                app.BackgroundClusters(parentIdx).PixelIdx(:);
            
            if numel(parentPix) < max(30, childK * 10)
                error('Parent cluster is too small for trial split.');
            end
            
            [ny, nx, nWn] = ...
                size(app.BackgroundInputCube);
            
            Xall = reshape( ...
                double(app.BackgroundInputCube), ...
                ny * nx, ...
                nWn);
            
            X = Xall(parentPix, :);
            
            good = all(isfinite(X), 2);
            
            X = X(good, :);
            parentPix = parentPix(good);
            
            rowMedian = median(X, 2, 'omitnan');
            Xnorm = X - rowMedian;
            
            rowNorm = sqrt(sum(Xnorm .^ 2, 2));
            rowNorm(rowNorm <= eps | ~isfinite(rowNorm)) = 1;
            
            Xnorm = Xnorm ./ rowNorm;
            
            rng(app.BackgroundRandomSeed + parentID + childK);
            
            maxTrain = min(size(Xnorm, 1), 10000);
            
            if size(Xnorm, 1) > maxTrain
                trainRows = randperm(size(Xnorm, 1), maxTrain);
            else
                trainRows = 1:size(Xnorm, 1);
            end
            
            [coeff, scoreTrain, ~, ~, explained, mu] = ...
                pca( ...
                Xnorm(trainRows, :), ...
                'Centered', true, ...
                'Economy', true);
            
            cumulative = cumsum(explained);
            
            nPC = find( ...
                cumulative >= app.BackgroundVarianceThreshold, ...
                1, ...
                'first');
            
            if isempty(nPC)
                nPC = size(coeff, 2);
            end
            
            nPC = min([ ...
                nPC, ...
                app.BackgroundMaxPC, ...
                size(coeff, 2)]);
            
            nPC = max(1, nPC);
            
            [trainLabels, centroids] = ...
                kmeans( ...
                scoreTrain(:, 1:nPC), ...
                childK, ...
                'Replicates', ...
                app.BackgroundReplicates, ...
                'MaxIter', 500, ...
                'Distance', 'sqeuclidean', ...
                'EmptyAction', 'singleton');
            
            scoreAll = ...
                (Xnorm - mu) * coeff(:, 1:nPC);
            
            d = pdist2( ...
                scoreAll, ...
                centroids, ...
                'squaredeuclidean');
            
            [~, childLabels] = min(d, [], 2);
            
            % =========================================================
            % CHILD SILHOUETTE
            % =========================================================
            
            maxSil = min( ...
                app.BackgroundSilhouetteMaxSpectra, ...
                size(scoreAll, 1));
            
            if size(scoreAll, 1) > maxSil
                
                sampleRows = ...
                    app.stratifiedBackgroundMetricSample( ...
                    childLabels, ...
                    maxSil);
                
            else
                sampleRows = (1:size(scoreAll, 1))';
            end
            
            try
                childSilhouette = silhouette( ...
                    scoreAll(sampleRows, :), ...
                    childLabels(sampleRows), ...
                    'sqeuclidean');
                
                localMacroValues = nan(childK, 1);
                
                for k = 1:childK
                    
                    localMacroValues(k) = mean( ...
                        childSilhouette( ...
                        childLabels(sampleRows) == k), ...
                        'omitnan');
                end
                
                localMacroSilhouette = ...
                    mean(localMacroValues, 'omitnan');
                
            catch
                localMacroSilhouette = NaN;
            end
            
            % =========================================================
            % DISPERSION REDUCTION
            % =========================================================
            
            parentCentroid = ...
                mean(scoreAll, 1, 'omitnan');
            
            parentDistances = sqrt(sum( ...
                (scoreAll - parentCentroid) .^ 2, ...
                2));
            
            parentDispersion = ...
                median(parentDistances, 'omitnan');
            
            weightedChildDispersion = 0;
            
            childFractions = zeros(childK, 1);
            childSizes = zeros(childK, 1);
            childMasks = cell(childK, 1);
            
            for k = 1:childK
                
                rows = childLabels == k;
                
                childSizes(k) = nnz(rows);
                childFractions(k) = ...
                    childSizes(k) / numel(childLabels);
                
                childCentroid = ...
                    mean(scoreAll(rows, :), 1, 'omitnan');
                
                childDistances = sqrt(sum( ...
                    (scoreAll(rows, :) - childCentroid) .^ 2, ...
                    2));
                
                childDispersion = ...
                    median(childDistances, 'omitnan');
                
                weightedChildDispersion = ...
                    weightedChildDispersion + ...
                    childFractions(k) * childDispersion;
                
                mask = false(ny, nx);
                mask(parentPix(rows)) = true;
                childMasks{k} = mask;
            end
            
            dispersionReduction = ...
                1 - ...
                weightedChildDispersion / ...
                max(parentDispersion, eps);
            
            % =========================================================
            % CHILD STABILITY
            % =========================================================
            
            childStabilityVector = ...
                app.computeBackgroundPixelStability( ...
                scoreAll(sampleRows, :), ...
                childLabels(sampleRows), ...
                unique(childLabels(sampleRows), 'stable'));
            
            childStability = ...
                mean(childStabilityVector, 'omitnan');
            
            % =========================================================
            % SPATIAL COHERENCE
            % =========================================================
            
            isolatedFractions = zeros(childK, 1);
            
            for k = 1:childK
                
                CC = bwconncomp(childMasks{k}, 8);
                sizes = cellfun(@numel, CC.PixelIdxList);
                
                if isempty(sizes)
                    isolatedFractions(k) = 1;
                else
                    isolatedFractions(k) = ...
                        sum(sizes(sizes <= 2)) / ...
                        max(sum(sizes), 1);
                end
            end
            
            % =========================================================
            % CHILD SPECTRAL DISTINCTNESS
            % =========================================================
            
            meanSpectra = zeros(childK, nWn);
            
            for k = 1:childK
                meanSpectra(k, :) = ...
                    mean(Xnorm(childLabels == k, :), ...
                    1, ...
                    'omitnan');
            end
            
            pairAngles = [];
            
            for a = 1:childK - 1
                
                for b = a + 1:childK
                    
                    numerator = ...
                        dot(meanSpectra(a, :), ...
                        meanSpectra(b, :));
                    
                    denominator = ...
                        norm(meanSpectra(a, :)) * ...
                        norm(meanSpectra(b, :));
                    
                    cosineValue = ...
                        numerator / max(denominator, eps);
                    
                    cosineValue = ...
                        max(-1, min(1, cosineValue));
                    
                    pairAngles(end + 1, 1) = ...
                        acos(cosineValue); %#ok<AGROW>
                end
            end
            
            medianChildSpectralAngle = ...
                median(pairAngles, 'omitnan');
            
            % =========================================================
            % ACCEPTANCE CHECKS
            % =========================================================
            
            checks = struct();
            
            checks.Silhouette = ...
                isfinite(localMacroSilhouette) && ...
                localMacroSilhouette >= 0.25;
            
            checks.Stability = ...
                isfinite(childStability) && ...
                childStability >= 0.80;
            
            checks.Dispersion = ...
                isfinite(dispersionReduction) && ...
                dispersionReduction >= 0.20;
            
            checks.MinimumSize = ...
                min(childFractions) >= 0.05;
            
            checks.SpatialCoherence = ...
                max(isolatedFractions) <= 0.15;
            
            checks.SpectralDistinctness = ...
                isfinite(medianChildSpectralAngle) && ...
                medianChildSpectralAngle >= 0.03;
            
            passed = sum(struct2array(checks));
            
            hardFail = ...
                ~checks.Stability || ...
                ~checks.MinimumSize;
            
            if passed >= 5 && ~hardFail
                
                recommendation = 'RECOMMENDED';
                
            elseif passed >= 3 && ~hardFail
                
                recommendation = 'REVIEW';
                
            else
                
                recommendation = 'NOT RECOMMENDED';
            end
            
            trial.ParentID = parentID;
            trial.ChildK = childK;
            trial.ParentPixelIdx = parentPix;
            trial.ChildLabels = childLabels;
            trial.ChildSizes = childSizes;
            trial.ChildFractions = childFractions;
            trial.NumPCUsed = nPC;
            trial.ExplainedVariance = sum(explained(1:nPC));
            trial.LocalMacroSilhouette = localMacroSilhouette;
            trial.ParentDispersion = parentDispersion;
            trial.WeightedChildDispersion = ...
                weightedChildDispersion;
            trial.DispersionReduction = ...
                dispersionReduction;
            trial.ChildStability = childStability;
            trial.ChildIsolatedFractions = ...
                isolatedFractions;
            trial.MedianChildSpectralAngle = ...
                medianChildSpectralAngle;
            trial.Checks = checks;
            trial.PassedChecks = passed;
            trial.Recommendation = recommendation;
            trial.Coefficients = coeff;
            trial.Mean = mu;
            trial.Centroids = centroids;
            trial.CreatedDate = ...
                datestr(now, 'yyyy-mm-dd HH:MM:SS');
        end
        
        
        function txt = passFailText(app, tf)
            
            if tf
                txt = 'PASS';
            else
                txt = 'FAIL';
            end
        end
        
        
        function action = chooseFinishAction(app, stageName)
            
            stageName = ...
                lower(strtrim(char(stageName)));
            
            switch stageName
                
                case 'cosmic'
                    
                    options = { ...
                        'Continue to Artifact', ...
                        'Stay in Cosmic', ...
                        'Return to scanspectra', ...
                        'Return to Home', ...
                        'Close Pretreat'};
                    
                    promptText = { ...
                        'Cosmic result has been saved.'; ...
                        'Choose the next action.'};
                    
                case 'artifact'
                    
                    % Background UI is not present in the current app.
                    options = { ...
                        'Stay in Artifact', ...
                        'Redo Cosmic', ...
                        'Return to scanspectra', ...
                        'Return to Home', ...
                        'Close Pretreat'};
                    
                    promptText = { ...
                        'Artifact result has been saved.'; ...
                        ''; ...
                        'The current app contains Cosmic and Artifact only.'; ...
                        'Choose the next action.'};
                    
                otherwise
                    
                    error( ...
                        'Unknown or unavailable Finish stage: %s', ...
                        stageName);
            end
            
            [idx, tf] = ...
                listdlg( ...
                'PromptString', ...
                promptText, ...
                'SelectionMode', ...
                'single', ...
                'ListString', ...
                options, ...
                'InitialValue', ...
                1, ...
                'Name', ...
                [upper(stageName) ' Finish'], ...
                'ListSize', ...
                [390 220]);
            
            if ~tf
                
                action = ...
                    '';
                
            else
                
                action = ...
                    options{idx};
            end
        end


        function invalidateBackgroundResult(app)
            
            % =============================================================
            % INVALIDATE BACKGROUND STATE ONLY
            %
            % The current scanpretreat UI contains Cosmic and Artifact tabs,
            % but does not contain the Background UI components such as:
            %   Tree2
            %   TextArea_3
            %   TextArea_4
            %   BackgroundremovalTab
            %
            % Therefore this function must not access Background UI controls.
            % =============================================================
            
            app.BackgroundReady = ...
                false;
            
            app.BackgroundValidated = ...
                false;
            
            app.BackgroundConfirmed = ...
                false;
            
            % =============================================================
            % INPUT AND PREVIEW
            % =============================================================
            
            app.BackgroundInputCube = ...
                [];
            
            app.BackgroundValidMask2D = ...
                logical.empty;
            
            app.BackgroundArtifactMask2D = ...
                logical.empty;
            
            app.BackgroundPreviewMap = ...
                [];
            
            app.BackgroundPreviewLabel = ...
                '';
            
            % =============================================================
            % SEGMENTATION RESULTS
            % =============================================================
            
            app.BackgroundLabelMap = ...
                [];
            
            app.BackgroundConfidenceMap = ...
                [];
            
            app.BackgroundClusters = ...
                app.makeEmptyBackgroundClusters();
            
            app.BackgroundNextClusterID = ...
                1;
            
            app.BackgroundSelectedClusterIDs = ...
                [];
            
            app.BackgroundCellMask2D = ...
                logical.empty;
            
            app.BackgroundBackgroundMask2D = ...
                logical.empty;
            
            app.BackgroundUncertainMask2D = ...
                logical.empty;
            
            app.BackgroundUnassignedMask2D = ...
                logical.empty;
            
            app.BackgroundManualClassMap = ...
                uint8.empty;
            
            % =============================================================
            % PCA / CLUSTER MODEL
            % =============================================================
            
            app.BackgroundNumPCUsed = ...
                0;
            
            app.BackgroundExplained = ...
                [];
            
            app.BackgroundPCAcoeff = ...
                [];
            
            app.BackgroundPCAmu = ...
                [];
            
            app.BackgroundAllScores = ...
                [];
            
            app.BackgroundMetricLabels = ...
                [];
            
            app.BackgroundMetricPixelIdx = ...
                [];
            
            app.BackgroundCentroids = ...
                [];
            
            app.BackgroundTrainingLocalIdx = ...
                [];
            
            app.BackgroundTrainingLinearIdx = ...
                [];
            
            % =============================================================
            % SCIENTIFIC QC
            % =============================================================
            
            app.BackgroundSilhouetteValues = ...
                [];
            
            app.BackgroundPixelStability = ...
                [];
            
            app.BackgroundGlobalMetrics = ...
                struct();
            
            app.BackgroundClusterMetrics = ...
                app.BackgroundClusterMetrics([]);
            
            app.BackgroundLastSplitTrial = ...
                struct();
            
            % =============================================================
            % HISTORY
            % =============================================================
            
            app.BackgroundUndoStack = ...
                {};
            
            app.BackgroundRedoStack = ...
                {};
            
            app.BackgroundLastSavedPath = ...
                '';
            
            % =============================================================
            % IMPORTANT
            %
            % Do not call:
            %   populateBackgroundTree
            %   updateBackgroundInfo
            %   updateBackgroundQC
            %   displayBackgroundView
            %
            % Those functions require Background UI components that are not
            % present in the current app design.
            % =============================================================
        end
        
        
        function invalidateArtifactAndBackground(app)
            
            % Artifact state
            app.ArtifactReady = false;
            app.ArtifactConfirmed = false;
            
            app.ArtifactMask2D = logical.empty;
            app.ValidPixelMask2D = logical.empty;
            
            app.ArtifactDisplayMapRaw = [];
            app.ArtifactDisplayMapClean = [];
            app.ArtifactDisplayLabel = '';
            
            app.ArtifactStripeScoreMap = [];
            app.ArtifactBandConsistencyMap = [];
            app.ArtifactSpectralZMap = [];
            
            app.ArtifactStrongSeedMask = logical.empty;
            app.ArtifactWeakSupportMask = logical.empty;
            app.ArtifactHysteresisMask = logical.empty;
            app.ArtifactRowBandMask = logical.empty;
            
            app.ArtifactRowEvidence = struct();
            app.ArtifactTable = table();
            
            app.ArtifactBandMaps = [];
            app.ArtifactBandLabels = {};
            app.ArtifactZStack = [];
            
            app.ArtifactDisplayInpaintCube = [];
            app.ArtifactDisplayInpaintInfo = struct();
            
            % Background depends on Artifact, therefore invalidate it too.
            app.invalidateBackgroundResult();
            
            app.ArtifactParams = ...
                app.getArtifactPresetParams( ...
                app.ModeDropDown.Value);
            
            app.plotArtifactInitialView();
        end
        
        function clustersOut = normalizeBackgroundClusterStruct(app, clustersIn)
            
            clustersOut = struct( ...
                'ID', {}, ...
                'Name', {}, ...
                'ParentID', {}, ...
                'PixelIdx', {}, ...
                'Class', {}, ...
                'Active', {}, ...
                'MeanSpectrum', {}, ...
                'StdSpectrum', {}, ...
                'Metrics', {});
            
            if isempty(clustersIn) || ~isstruct(clustersIn)
                return
            end
            
            for i = 1:numel(clustersIn)
                
                old = clustersIn(i);
                
                C = struct();
                
                C.ID = i;
                C.Name = sprintf('Cluster %d', i);
                C.ParentID = 0;
                C.PixelIdx = [];
                C.Class = 'Unassigned';
                C.Active = true;
                C.MeanSpectrum = [];
                C.StdSpectrum = [];
                C.Metrics = struct();
                
                if isfield(old, 'ID') && ~isempty(old.ID)
                    C.ID = double(old.ID);
                end
                
                if isfield(old, 'Name') && ~isempty(old.Name)
                    C.Name = char(old.Name);
                end
                
                if isfield(old, 'ParentID') && ~isempty(old.ParentID)
                    C.ParentID = double(old.ParentID);
                end
                
                if isfield(old, 'PixelIdx') && ~isempty(old.PixelIdx)
                    C.PixelIdx = double(old.PixelIdx(:));
                end
                
                if isfield(old, 'Class') && ~isempty(old.Class)
                    C.Class = char(old.Class);
                end
                
                if isfield(old, 'Active') && ~isempty(old.Active)
                    C.Active = logical(old.Active);
                end
                
                if isfield(old, 'MeanSpectrum') && ...
                        ~isempty(old.MeanSpectrum)
                    
                    C.MeanSpectrum = ...
                        double(old.MeanSpectrum);
                end
                
                if isfield(old, 'StdSpectrum') && ...
                        ~isempty(old.StdSpectrum)
                    
                    C.StdSpectrum = ...
                        double(old.StdSpectrum);
                end
                
                if isfield(old, 'Metrics') && ...
                        isstruct(old.Metrics)
                    
                    C.Metrics = old.Metrics;
                end
                
                clustersOut(end + 1) = C; %#ok<AGROW>
            end
        end
        
        function clusters = makeEmptyBackgroundClusters(app)
            
            clusters = struct( ...
                'ID', {}, ...
                'Name', {}, ...
                'ParentID', {}, ...
                'PixelIdx', {}, ...
                'Class', {}, ...
                'Active', {}, ...
                'MeanSpectrum', {}, ...
                'StdSpectrum', {}, ...
                'Metrics', {});
        end
        
        
        function resolveSpectralResolution(app, payload)
            
            % =============================================================
            % SPECTRAL SAMPLING
            % =============================================================
            
            dWN = ...
                abs(diff(double(app.Wavenumber(:))));
            
            dWN = ...
                dWN(isfinite(dWN) & dWN > 0);
            
            if isempty(dWN)
                app.SpectralSampling = 1;
            else
                app.SpectralSampling = ...
                    median(dWN, 'omitnan');
            end
            
            % =============================================================
            % RESOLUTION FROM PAYLOAD
            % =============================================================
            
            resolution = ...
                NaN;
            
            sourceText = ...
                '';
            
            candidateFields = { ...
                'SpectralResolution', ...
                'SpectralResolutionCm1', ...
                'Resolution', ...
                'ResolutionCm1'};
            
            for k = 1:numel(candidateFields)
                
                fieldName = ...
                    candidateFields{k};
                
                if isfield(payload, fieldName)
                    
                    value = ...
                        double(payload.(fieldName));
                    
                    if isscalar(value) && ...
                            isfinite(value) && ...
                            value > 0
                        
                        resolution = ...
                            value;
                        
                        sourceText = ...
                            ['payload.' fieldName];
                        
                        break
                    end
                end
            end
            
            % =============================================================
            % ESTIMATED FALLBACK
            %
            % Sampling is not exactly instrument FWHM resolution. This is only
            % an explicit fallback when no calibrated value exists.
            % =============================================================
            
            if ~isfinite(resolution)
                
                resolution = ...
                    max(app.SpectralSampling, eps);
                
                sourceText = ...
                    'estimated from median wavenumber sampling';
            end
            
            app.SpectralResolution = ...
                resolution;
            
            app.SpectralResolutionSource = ...
                sourceText;
        end
        
        
        function updateScanInformationBox(app)
            
            if isempty(app.CubeOriginal) || ...
                    isempty(app.Wavenumber)
                
                app.TextArea_5.Value = { ...
                    'No Raman scan loaded.'};
                
                return
            end
            
            [ny, nx, nWn] = ...
                size(app.CubeOriginal);
            
            finiteMask = ...
                all(isfinite(app.CubeOriginal), 3);
            
            usableMask = ...
                app.getCurrentUsableSpectrumMask();
            
            if isempty(usableMask)
                usableMask = finiteMask;
            end
            
            totalFinite = ...
                nnz(finiteMask);
            
            usableCount = ...
                nnz(usableMask);
            
            if app.ROIReady && ...
                    ~isempty(app.CurrentROIMask)
                
                spatialScope = ...
                    sprintf( ...
                    'ROI: %d pixels', ...
                    nnz(app.CurrentROIMask));
                
            else
                
                spatialScope = ...
                    'Full finite scan';
            end
            
            sourcePath = ...
                app.CurrentMatPath;
            
            if isempty(sourcePath)
                sourcePath = ...
                    'In-memory scan';
            end
            
            app.TextArea_5.Editable = ...
                'off';
            
            app.TextArea_5.FontName = ...
                'Consolas';
            
            app.TextArea_5.Value = { ...
                'SCAN INFORMATION'; ...
                '------------------------'; ...
                sprintf('SCAN        %s', ...
                app.CurrentScanName); ...
                sprintf('ID          %s', ...
                app.getCurrentScanID()); ...
                sprintf('SIZE        %d x %d x %d', ...
                ny, nx, nWn); ...
                sprintf('WN RANGE    %.2f - %.2f cm^{-1}', ...
                min(app.Wavenumber), ...
                max(app.Wavenumber)); ...
                sprintf('SAMPLING    %.4g cm^{-1}/point', ...
                app.SpectralSampling); ...
                sprintf('RESOLUTION  %.4g cm^{-1}', ...
                app.SpectralResolution); ...
                sprintf('SCOPE       %s', ...
                spatialScope); ...
                sprintf('USABLE      %d / %d', ...
                usableCount, ...
                totalFinite); ...
                ''; ...
                'SOURCE'; ...
                sourcePath};
        end
        
        
        function plotAllDetectedCandidateSpectraReview(app)
            
            if isempty(app.CandidateTable)
                return
            end
            
            T = ...
                app.ensureCosmicCandidateTableSchema( ...
                app.CandidateTable);
            
            cube = ...
                app.getActiveCube();
            
            reviewIdx = ...
                (1:height(T))';
            
            if numel(reviewIdx) > app.MaxReviewPlotSpectra
                
                rng(1);
                
                reviewIdx = ...
                    reviewIdx( ...
                    randperm( ...
                    numel(reviewIdx), ...
                    app.MaxReviewPlotSpectra));
            end
            
            app.resetSpectrumAxis( ...
                app.UIAxes);
            
            hold(app.UIAxes, 'on');
            
            yValues = [];
            
            for n = 1:numel(reviewIdx)
                
                i = ...
                    reviewIdx(n);
                
                spectrum = ...
                    squeeze( ...
                    cube( ...
                    T.Y(i), ...
                    T.X(i), ...
                    :));
                
                plot( ...
                    app.UIAxes, ...
                    app.Wavenumber, ...
                    spectrum, ...
                    'Color', ...
                    [0.78 0.78 0.78], ...
                    'LineWidth', ...
                    0.3);
                
                kPeak = ...
                    T.KPeak(i);
                
                if kPeak >= 1 && ...
                        kPeak <= numel(spectrum)
                    
                    if strcmpi(string(T.Source(i)), "Manual")
                        
                        markerColor = ...
                            [0.10 0.35 0.85];
                        
                    else
                        
                        markerColor = ...
                            [0.85 0.15 0.10];
                    end
                    
                    plot( ...
                        app.UIAxes, ...
                        app.Wavenumber(kPeak), ...
                        spectrum(kPeak), ...
                        '.', ...
                        'Color', ...
                        markerColor, ...
                        'MarkerSize', ...
                        8);
                end
                
                yValues = ...
                    [yValues; spectrum(:)]; %#ok<AGROW>
            end
            
            hold(app.UIAxes, 'off');
            
            title( ...
                app.UIAxes, ...
                sprintf( ...
                'All detected candidate spectra | displayed=%d / total=%d', ...
                numel(reviewIdx), ...
                height(T)), ...
                'Interpreter', ...
                'none');
            
            app.setNiceSpectralLimits( ...
                app.UIAxes, ...
                app.Wavenumber, ...
                yValues);
            
            [ny, nx, ~] = ...
                size(cube);
            
            countMap = ...
                zeros(ny, nx);
            
            for i = 1:height(T)
                
                if T.Y(i) >= 1 && ...
                        T.Y(i) <= ny && ...
                        T.X(i) >= 1 && ...
                        T.X(i) <= nx
                    
                    countMap(T.Y(i), T.X(i)) = ...
                        countMap(T.Y(i), T.X(i)) + 1;
                end
            end
            
            app.resetImageAxis( ...
                app.UIAxes2);
            
            imagesc( ...
                app.UIAxes2, ...
                countMap);
            
            axis(app.UIAxes2, 'image');
            
            app.UIAxes2.YDir = ...
                'normal';
            
            colormap(app.UIAxes2, 'hot');
            colorbar(app.UIAxes2);
            
            title( ...
                app.UIAxes2, ...
                'All detected candidate count map', ...
                'Interpreter', ...
                'none');
            
            automaticCount = ...
                nnz(strcmpi(string(T.Source), "Automatic"));
            
            manualCount = ...
                nnz(strcmpi(string(T.Source), "Manual"));
            
            app.setText( ...
                app.makeCosmicWorkflowStatusText( ...
                'All detected candidates review', ...
                { ...
                sprintf('Total candidates: %d', ...
                height(T)); ...
                sprintf('Automatic: %d', ...
                automaticCount); ...
                sprintf('Manual: %d', ...
                manualCount); ...
                sprintf('Displayed spectra: %d', ...
                numel(reviewIdx)); ...
                ''; ...
                'Axis 1: candidate spectra and detected event markers.'; ...
                'Axis 2: candidate count per spatial pixel.'}));
        end
        
        
        function mask = getCurrentUsableSpectrumMask(app)
            
            if isempty(app.CubeOriginal)
                
                mask = ...
                    logical.empty;
                
                return
            end
            
            [ny, nx, ~] = ...
                size(app.CubeOriginal);
            
            mask = ...
                all(isfinite(app.CubeOriginal), 3);
            
            if ~isempty(app.CurrentROIMask) && ...
                    isequal(size(app.CurrentROIMask), [ny nx])
                
                mask = ...
                    mask & logical(app.CurrentROIMask);
            end
            
            if ~isempty(app.DeletedSpectrumMask2D) && ...
                    isequal(size(app.DeletedSpectrumMask2D), [ny nx])
                
                mask = ...
                    mask & ~logical(app.DeletedSpectrumMask2D);
            end
        end


        function setCosmicWorkflowControls(app, stateName)
            
            stateName = ...
                lower(string(stateName));
            
            hasScan = ...
                ~isempty(app.CubeOriginal);
            
            hasCandidates = ...
                ~isempty(app.CandidateTable) && ...
                height(app.CandidateTable) > 0;
            
            hasAccepted = ...
                hasCandidates && ...
                any(app.CandidateTable.Accepted);
            
            hasCorrectedCube = ...
                ~isempty(app.CorrectedCube);
            
            % Detection controls remain available whenever a scan exists.
            app.CosmicdetectionButton.Enable = ...
                app.onOff(hasScan);
            
            app.ModesDropDown.Enable = ...
                app.onOff(hasScan);
            
            app.ManualdetectButton.Enable = ...
                app.onOff(hasScan);
            
            app.DeletespectraButton.Enable = ...
                app.onOff(hasScan);
            
            % Review decisions require candidates.
            app.Button.Enable = ...
                app.onOff(hasCandidates);
            
            app.Button_4.Enable = ...
                app.onOff(hasCandidates);
            
            app.Button_2.Enable = ...
                app.onOff(hasCandidates);
            
            app.Button_3.Enable = ...
                app.onOff(hasCandidates);
            
            app.ConfirmfinalcosmiclistButton.Enable = ...
                app.onOff(hasCandidates);
            
            % Correction controls remain available when accepted events exist.
            app.MethodsDropDown_2.Enable = ...
                app.onOff(hasAccepted);
            
            app.ModesDropDown_2.Enable = ...
                app.onOff(hasAccepted);
            
            app.TrialcorrectionButton.Enable = ...
                app.onOff(hasAccepted);
            
            app.ApplyforallButton.Enable = ...
                app.onOff(hasAccepted);
            
            % Review must always be available once meaningful data exists.
            app.ReviewButton.Enable = ...
                app.onOff( ...
                hasScan || ...
                hasCandidates || ...
                hasCorrectedCube);
            
            app.FinishButton.Enable = ...
                app.onOff(hasCorrectedCube);
        end


        function createCandidateTreeNode(app, parentNode, candidateIndex)
            
            txt = ...
                app.makeCandidateNodeText( ...
                candidateIndex);
            
            node = ...
                uitreenode( ...
                parentNode, ...
                'Text', ...
                txt, ...
                'NodeData', ...
                struct( ...
                'Type', ...
                'candidate', ...
                'Index', ...
                candidateIndex));
            
            if app.CandidateTable.Corrected(candidateIndex)
                
                addStyle( ...
                    app.Tree, ...
                    app.CorrectedNodeStyle, ...
                    'node', ...
                    node);
                
            elseif app.CandidateTable.Reviewed(candidateIndex) && ...
                    app.CandidateTable.Accepted(candidateIndex)
                
                addStyle( ...
                    app.Tree, ...
                    app.AgreeNodeStyle, ...
                    'node', ...
                    node);
                
            elseif app.CandidateTable.Reviewed(candidateIndex)
                
                addStyle( ...
                    app.Tree, ...
                    app.IgnoreNodeStyle, ...
                    'node', ...
                    node);
                
            else
                
                addStyle( ...
                    app.Tree, ...
                    app.PendingNodeStyle, ...
                    'node', ...
                    node);
            end
        end
        
        
        function value = onOff(app, conditionValue)
            
            if conditionValue
                value = 'on';
            else
                value = 'off';
            end
        end
        
        
        function lines = makeCosmicWorkflowStatusText( ...
                app, ...
                titleText, ...
                detailLines)
            
            % =============================================================
            % BUILD COSMIC WORKFLOW STATUS TEXT
            %
            % Always return one vertical cell array of character vectors.
            % Do not use detailLines{:} inside a cell constructor because a
            % comma-separated expansion can create incompatible dimensions.
            % =============================================================
            
            if nargin < 2 || ...
                    isempty(titleText)
                
                titleText = ...
                    'Cosmic workflow';
            end
            
            if nargin < 3 || ...
                    isempty(detailLines)
                
                detailLines = ...
                    {};
            end
            
            % =============================================================
            % 1. NORMALIZE TITLE
            % =============================================================
            
            titleText = ...
                char(string(titleText));
            
            if isempty(titleText)
                titleText = ...
                    'Cosmic workflow';
            end
            
            % =============================================================
            % 2. NORMALIZE DETAIL LINES TO A COLUMN CELL ARRAY
            % =============================================================
            
            if ischar(detailLines)
                
                detailLines = ...
                    {detailLines};
                
            elseif isstring(detailLines)
                
                detailLines = ...
                    cellstr(detailLines(:));
                
            elseif iscell(detailLines)
                
                detailLines = ...
                    detailLines(:);
                
            else
                
                detailLines = ...
                    {char(string(detailLines))};
            end
            
            % Convert every detail item safely to one character row.
            normalizedDetails = ...
                cell(numel(detailLines), 1);
            
            for k = 1:numel(detailLines)
                
                value = ...
                    detailLines{k};
                
                if isempty(value)
                    
                    normalizedDetails{k} = ...
                        '';
                    
                elseif ischar(value)
                    
                    % A multi-row char matrix must be split into separate lines.
                    if size(value, 1) <= 1
                        
                        normalizedDetails{k} = ...
                            value;
                        
                    else
                        
                        normalizedDetails{k} = ...
                            strjoin( ...
                            cellstr(value), ...
                            ' ');
                    end
                    
                elseif isstring(value)
                    
                    value = ...
                        value(:);
                    
                    if isempty(value)
                        
                        normalizedDetails{k} = ...
                            '';
                        
                    elseif numel(value) == 1
                        
                        normalizedDetails{k} = ...
                            char(value);
                        
                    else
                        
                        normalizedDetails{k} = ...
                            char(strjoin(value, ' '));
                    end
                    
                elseif isnumeric(value) || ...
                        islogical(value)
                    
                    if isscalar(value)
                        
                        normalizedDetails{k} = ...
                            num2str(value);
                        
                    else
                        
                        normalizedDetails{k} = ...
                            mat2str(value);
                    end
                    
                else
                    
                    try
                        
                        normalizedDetails{k} = ...
                            char(string(value));
                        
                    catch
                        
                        normalizedDetails{k} = ...
                            '<unsupported detail value>';
                    end
                end
            end
            
            detailLines = ...
                normalizedDetails(:);
            
            % =============================================================
            % 3. SANITIZE DETECTION PARAMETERS
            % =============================================================
            
            D = ...
                app.sanitizeDetectionParams( ...
                app.DetectionParams);
            
            % =============================================================
            % 4. SANITIZE CORRECTION PARAMETERS
            % =============================================================
            
            C = ...
                app.CorrectParams;
            
            if isempty(C) || ...
                    ~isstruct(C)
                
                C = ...
                    struct();
            end
            
            if ~isfield(C, 'Method') || ...
                    isempty(C.Method)
                
                try
                    
                    C.Method = ...
                        string(app.MethodsDropDown_2.Value);
                    
                catch
                    
                    C.Method = ...
                        "Not selected";
                end
            end
            
            if ~isfield(C, 'ExpandPoints') || ...
                    isempty(C.ExpandPoints) || ...
                    ~isscalar(C.ExpandPoints) || ...
                    ~isfinite(double(C.ExpandPoints))
                
                C.ExpandPoints = ...
                    0;
            end
            
            if ~isfield(C, 'SupportPoints') || ...
                    isempty(C.SupportPoints) || ...
                    ~isscalar(C.SupportPoints) || ...
                    ~isfinite(double(C.SupportPoints))
                
                C.SupportPoints = ...
                    3;
            end
            
            if ~isfield(C, 'MedianWindow') || ...
                    isempty(C.MedianWindow) || ...
                    ~isscalar(C.MedianWindow) || ...
                    ~isfinite(double(C.MedianWindow))
                
                C.MedianWindow = ...
                    7;
            end
            
            C.ExpandPoints = ...
                max(0, round(double(C.ExpandPoints)));
            
            C.SupportPoints = ...
                max(1, round(double(C.SupportPoints)));
            
            C.MedianWindow = ...
                max(3, round(double(C.MedianWindow)));
            
            % =============================================================
            % 5. BUILD EACH SECTION SEPARATELY
            %
            % Every section is explicitly a vertical cell array.
            % =============================================================
            
            headerLines = { ...
                upper(titleText); ...
                '------------------------'};
            
            detectionLines = { ...
                ''; ...
                'DETECTION'; ...
                sprintf( ...
                'Mode: %s', ...
                char(D.Mode)); ...
                sprintf( ...
                'Method: %s', ...
                char(D.Method)); ...
                sprintf( ...
                'ThresholdK / MinJumpK: %.2f / %.2f', ...
                D.ThresholdK, ...
                D.MinJumpK); ...
                sprintf( ...
                'Window / max width: %d / %d points', ...
                D.LocalWindow, ...
                D.MaxSpikeWidth); ...
                sprintf( ...
                'Merge / auto width: %.2fR / %.2fR', ...
                D.MergeDistanceR, ...
                D.MaxAutomaticWidthR); ...
                sprintf( ...
                'Support return: %.2f Z', ...
                D.SupportReturnK); ...
                sprintf( ...
                'Weak fractions: %.2f / %.2f', ...
                D.WeakDerivativeFraction, ...
                D.WeakResidualFraction)};
            
            correctionLines = { ...
                ''; ...
                'CORRECTION'; ...
                sprintf( ...
                'Method: %s', ...
                char(string(C.Method))); ...
                sprintf( ...
                'Expand / support: %d / %d points', ...
                C.ExpandPoints, ...
                C.SupportPoints); ...
                sprintf( ...
                'Median window: %d points', ...
                C.MedianWindow)};
            
            % =============================================================
            % 6. VERTICAL CONCATENATION ONLY
            % =============================================================
            
            lines = ...
                [ ...
                headerLines(:); ...
                detailLines(:); ...
                detectionLines(:); ...
                correctionLines(:) ...
                ];
        end
        
        

        function T = buildResolutionAwareCandidateTable( ...
                app, ...
                strongSeed, ...
                weakSupport, ...
                scoreVol, ...
                residualVol, ...
                params)
            
            % =============================================================
            % BUILD RESOLUTION-AWARE COSMIC EVENT TABLE
            %
            % Important:
            % This version does not use cell2table or cell2mat.
            % Every output column is created with a fixed MATLAB data type.
            % =============================================================
            
            [ny, nx, nWn] = ...
                size(strongSeed);
            
            spatialPixels = ...
                find(any(strongSeed, 3));
            
            if isempty(spatialPixels)
                
                T = ...
                    app.makeEmptyCosmicCandidateTable();
                
                return
            end
            
            sampling = ...
                double(app.SpectralSampling);
            
            if ~isscalar(sampling) || ...
                    ~isfinite(sampling) || ...
                    sampling <= 0
                
                sampling = ...
                    median( ...
                    abs(diff(double(app.Wavenumber(:)))), ...
                    'omitnan');
            end
            
            if ~isscalar(sampling) || ...
                    ~isfinite(sampling) || ...
                    sampling <= 0
                
                sampling = 1;
            end
            
            resolution = ...
                double(app.SpectralResolution);
            
            if ~isscalar(resolution) || ...
                    ~isfinite(resolution) || ...
                    resolution <= 0
                
                resolution = sampling;
            end
            
            resolution = ...
                max(resolution, sampling);
            
            mergePoints = ...
                max( ...
                1, ...
                ceil(params.MergeDistanceR * ...
                resolution / sampling));
            
            supportReturnK = ...
                double(params.SupportReturnK);
            
            if ~isscalar(supportReturnK) || ...
                    ~isfinite(supportReturnK)
                
                supportReturnK = 2.5;
            end
            
            % =============================================================
            % PREALLOCATE TYPED OUTPUT ARRAYS
            %
            % The event count cannot exceed the total number of strong seeds.
            % =============================================================
            
            maxEvents = ...
                nnz(strongSeed);
            
            ID = ...
                zeros(maxEvents, 1);
            
            Y = ...
                zeros(maxEvents, 1);
            
            X = ...
                zeros(maxEvents, 1);
            
            KSeedStart = ...
                zeros(maxEvents, 1);
            
            KSeedEnd = ...
                zeros(maxEvents, 1);
            
            KStart = ...
                zeros(maxEvents, 1);
            
            KPeak = ...
                zeros(maxEvents, 1);
            
            KPeak2 = ...
                nan(maxEvents, 1);
            
            KEnd = ...
                zeros(maxEvents, 1);
            
            WN = ...
                zeros(maxEvents, 1);
            
            WN2 = ...
                nan(maxEvents, 1);
            
            EventType = ...
                strings(maxEvents, 1);
            
            FWHM_cm1 = ...
                zeros(maxEvents, 1);
            
            SupportWidth_cm1 = ...
                zeros(maxEvents, 1);
            
            ResolutionRatio = ...
                zeros(maxEvents, 1);
            
            DerivativeZ = ...
                zeros(maxEvents, 1);
            
            ResidualZ = ...
                zeros(maxEvents, 1);
            
            NeighborDiscordance = ...
                nan(maxEvents, 1);
            
            Score = ...
                zeros(maxEvents, 1);
            
            Source = ...
                strings(maxEvents, 1);
            
            Accepted = ...
                false(maxEvents, 1);
            
            Reviewed = ...
                false(maxEvents, 1);
            
            Corrected = ...
                false(maxEvents, 1);
            
            eventCount = 0;
            
            % =============================================================
            % PROCESS EACH SPATIAL PIXEL CONTAINING A STRONG SEED
            % =============================================================
            
            for p = 1:numel(spatialPixels)
                
                [y, x] = ...
                    ind2sub( ...
                    [ny nx], ...
                    spatialPixels(p));
                
                seedLine = ...
                    logical( ...
                    squeeze(strongSeed(y, x, :)));
                
                supportLine = ...
                    logical( ...
                    squeeze(weakSupport(y, x, :)));
                
                scoreLine = ...
                    double( ...
                    squeeze(scoreVol(y, x, :)));
                
                residualLine = ...
                    double( ...
                    squeeze(residualVol(y, x, :)));
                
                seedLine = ...
                    seedLine(:);
                
                supportLine = ...
                    supportLine(:);
                
                scoreLine = ...
                    scoreLine(:);
                
                residualLine = ...
                    residualLine(:);
                
                seedIdx = ...
                    find(seedLine);
                
                if isempty(seedIdx)
                    continue
                end
                
                % =========================================================
                % GROUP STRONG SEEDS
                %
                % Seeds separated by no more than MergeDistanceR are treated
                % as one possible single/doublet/complex event.
                % =========================================================
                
                breakLocations = ...
                    find(diff(seedIdx) > mergePoints);
                
                groupStart = ...
                    [1; breakLocations + 1];
                
                groupEnd = ...
                    [breakLocations; numel(seedIdx)];
                
                for groupNumber = 1:numel(groupStart)
                    
                    eventSeeds = ...
                        seedIdx( ...
                        groupStart(groupNumber): ...
                        groupEnd(groupNumber));
                    
                    if isempty(eventSeeds)
                        continue
                    end
                    
                    seedStart = ...
                        eventSeeds(1);
                    
                    seedEnd = ...
                        eventSeeds(end);
                    
                    % =====================================================
                    % EXPAND EVENT TO WEAK-SUPPORT ENVELOPE
                    % =====================================================
                    
                    kStart = ...
                        seedStart;
                    
                    kEnd = ...
                        seedEnd;
                    
                    while kStart > 1
                        
                        previousIndex = ...
                            kStart - 1;
                        
                        if supportLine(previousIndex) || ...
                                scoreLine(previousIndex) >= supportReturnK
                            
                            kStart = ...
                                previousIndex;
                            
                        else
                            break
                        end
                    end
                    
                    while kEnd < nWn
                        
                        nextIndex = ...
                            kEnd + 1;
                        
                        if supportLine(nextIndex) || ...
                                scoreLine(nextIndex) >= supportReturnK
                            
                            kEnd = ...
                                nextIndex;
                            
                        else
                            break
                        end
                    end
                    
                    eventIndices = ...
                        (kStart:kEnd)';
                    
                    localResidual = ...
                        residualLine(eventIndices);
                    
                    localScore = ...
                        scoreLine(eventIndices);
                    
                    finiteResidual = ...
                        isfinite(localResidual);
                    
                    finiteScore = ...
                        isfinite(localScore);
                    
                    if ~any(finiteResidual) || ...
                            ~any(finiteScore)
                        
                        continue
                    end
                    
                    % =====================================================
                    % PRIMARY PEAK
                    % =====================================================
                    
                    residualForMaximum = ...
                        localResidual;
                    
                    residualForMaximum(~finiteResidual) = ...
                        -Inf;
                    
                    [peakResidual, peakOffset] = ...
                        max(residualForMaximum);
                    
                    if ~isfinite(peakResidual) || ...
                            peakResidual <= 0
                        
                        continue
                    end
                    
                    kPrimary = ...
                        eventIndices(peakOffset);
                    
                    % =====================================================
                    % FWHM
                    % =====================================================
                    
                    halfHeight = ...
                        0.5 * peakResidual;
                    
                    aboveHalf = ...
                        eventIndices( ...
                        finiteResidual & ...
                        localResidual >= halfHeight);
                    
                    if isempty(aboveHalf)
                        
                        fwhmStart = ...
                            kPrimary;
                        
                        fwhmEnd = ...
                            kPrimary;
                        
                    else
                        
                        fwhmStart = ...
                            aboveHalf(1);
                        
                        fwhmEnd = ...
                            aboveHalf(end);
                    end
                    
                    fwhmCm1 = ...
                        abs( ...
                        double(app.Wavenumber(fwhmEnd)) - ...
                        double(app.Wavenumber(fwhmStart))) + ...
                        sampling;
                    
                    supportWidthCm1 = ...
                        abs( ...
                        double(app.Wavenumber(kEnd)) - ...
                        double(app.Wavenumber(kStart))) + ...
                        sampling;
                    
                    resolutionRatio = ...
                        fwhmCm1 / resolution;
                    
                    % =====================================================
                    % LOCAL PEAKS FOR SINGLE / DOUBLET / COMPLEX
                    % =====================================================
                    
                    [peakLocations, peakValues] = ...
                        app.findEventLocalPeaks( ...
                        residualLine, ...
                        kStart, ...
                        kEnd);
                    
                    peakLocations = ...
                        double(peakLocations(:));
                    
                    peakValues = ...
                        double(peakValues(:));
                    
                    validPeaks = ...
                        isfinite(peakLocations) & ...
                        isfinite(peakValues) & ...
                        peakLocations >= kStart & ...
                        peakLocations <= kEnd;
                    
                    peakLocations = ...
                        peakLocations(validPeaks);
                    
                    peakValues = ...
                        peakValues(validPeaks);
                    
                    if isempty(peakLocations)
                        
                        peakLocations = ...
                            kPrimary;
                        
                        peakValues = ...
                            peakResidual;
                    end
                    
                    [peakValues, peakOrder] = ...
                        sort(peakValues, 'descend');
                    
                    peakLocations = ...
                        peakLocations(peakOrder);
                    
                    kPrimary = ...
                        round(peakLocations(1));
                    
                    kSecondary = ...
                        NaN;
                    
                    eventType = ...
                        "Single";
                    
                    if numel(peakLocations) >= 2
                        
                        kSecondary = ...
                            round(peakLocations(2));
                        
                        leftPeak = ...
                            min(kPrimary, kSecondary);
                        
                        rightPeak = ...
                            max(kPrimary, kSecondary);
                        
                        betweenResidual = ...
                            residualLine(leftPeak:rightPeak);
                        
                        finiteBetween = ...
                            isfinite(betweenResidual);
                        
                        if any(finiteBetween)
                            
                            temporaryResidual = ...
                                betweenResidual;
                            
                            temporaryResidual(~finiteBetween) = ...
                                Inf;
                            
                            [valleyHeight, valleyOffset] = ...
                                min(temporaryResidual);
                            
                            valleyIndex = ...
                                leftPeak + valleyOffset - 1;
                            
                        else
                            
                            valleyHeight = ...
                                NaN;
                            
                            valleyIndex = ...
                                NaN;
                        end
                        
                        smallerPeakHeight = ...
                            min( ...
                            residualLine(kPrimary), ...
                            residualLine(kSecondary));
                        
                        peakSeparationCm1 = ...
                            abs( ...
                            double(app.Wavenumber(kSecondary)) - ...
                            double(app.Wavenumber(kPrimary)));
                        
                        if isfinite(valleyHeight) && ...
                                isfinite(smallerPeakHeight) && ...
                                smallerPeakHeight > 0 && ...
                                valleyHeight < 0.85 * smallerPeakHeight
                            
                            eventType = ...
                                "Doublet";
                            
                        elseif peakSeparationCm1 <= ...
                                params.MergeDistanceR * resolution
                            
                            eventType = ...
                                "Complex narrow";
                            
                        else
                            
                            eventType = ...
                                "Multiple peaks";
                        end
                        
                        %#ok<NASGU>
                        valleyIndex = valleyIndex;
                    end
                    
                    if resolutionRatio > ...
                            params.MaxAutomaticWidthR
                        
                        eventType = ...
                            "Broad suspicious";
                    end
                    
                    % =====================================================
                    % EVENT EVIDENCE
                    % =====================================================
                    
                    eventScore = ...
                        max(localScore(finiteScore));
                    
                    seedScores = ...
                        scoreLine(eventSeeds);
                    
                    seedScores = ...
                        seedScores(isfinite(seedScores));
                    
                    if isempty(seedScores)
                        
                        derivativeZ = ...
                            eventScore;
                        
                    else
                        
                        derivativeZ = ...
                            max(seedScores);
                    end
                    
                    finiteLocalResidual = ...
                        localResidual(isfinite(localResidual));
                    
                    if isempty(finiteLocalResidual)
                        
                        residualZ = ...
                            NaN;
                        
                    else
                        
                        residualSigma = ...
                            app.robustSigmaSingle( ...
                            residualLine( ...
                            residualLine > 0 & ...
                            isfinite(residualLine)));
                        
                        residualZ = ...
                            max(finiteLocalResidual) / ...
                            max(residualSigma, eps);
                    end
                    
                    neighborDiscordance = ...
                        app.computeSpatialNeighborDiscordance( ...
                        y, ...
                        x, ...
                        kPrimary);
                    
                    % =====================================================
                    % APPEND TYPED EVENT
                    % =====================================================
                    
                    eventCount = ...
                        eventCount + 1;
                    
                    ID(eventCount) = ...
                        eventCount;
                    
                    Y(eventCount) = ...
                        y;
                    
                    X(eventCount) = ...
                        x;
                    
                    KSeedStart(eventCount) = ...
                        seedStart;
                    
                    KSeedEnd(eventCount) = ...
                        seedEnd;
                    
                    KStart(eventCount) = ...
                        kStart;
                    
                    KPeak(eventCount) = ...
                        kPrimary;
                    
                    KPeak2(eventCount) = ...
                        kSecondary;
                    
                    KEnd(eventCount) = ...
                        kEnd;
                    
                    WN(eventCount) = ...
                        double(app.Wavenumber(kPrimary));
                    
                    if isfinite(kSecondary) && ...
                            kSecondary >= 1 && ...
                            kSecondary <= numel(app.Wavenumber)
                        
                        WN2(eventCount) = ...
                            double(app.Wavenumber(kSecondary));
                    end
                    
                    EventType(eventCount) = ...
                        eventType;
                    
                    FWHM_cm1(eventCount) = ...
                        fwhmCm1;
                    
                    SupportWidth_cm1(eventCount) = ...
                        supportWidthCm1;
                    
                    ResolutionRatio(eventCount) = ...
                        resolutionRatio;
                    
                    DerivativeZ(eventCount) = ...
                        derivativeZ;
                    
                    ResidualZ(eventCount) = ...
                        residualZ;
                    
                    NeighborDiscordance(eventCount) = ...
                        neighborDiscordance;
                    
                    Score(eventCount) = ...
                        eventScore;
                    
                    Source(eventCount) = ...
                        "Automatic";
                    
                    Accepted(eventCount) = ...
                        false;
                    
                    Reviewed(eventCount) = ...
                        false;
                    
                    Corrected(eventCount) = ...
                        false;
                end
            end
            
            % =============================================================
            % NO VALID EVENT AFTER FILTERING
            % =============================================================
            
            if eventCount == 0
                
                T = ...
                    app.makeEmptyCosmicCandidateTable();
                
                return
            end
            
            % =============================================================
            % TRIM PREALLOCATED ARRAYS
            % =============================================================
            
            keep = ...
                1:eventCount;
            
            ID = ID(keep);
            Y = Y(keep);
            X = X(keep);
            
            KSeedStart = KSeedStart(keep);
            KSeedEnd = KSeedEnd(keep);
            
            KStart = KStart(keep);
            KPeak = KPeak(keep);
            KPeak2 = KPeak2(keep);
            KEnd = KEnd(keep);
            
            WN = WN(keep);
            WN2 = WN2(keep);
            
            EventType = EventType(keep);
            
            FWHM_cm1 = FWHM_cm1(keep);
            SupportWidth_cm1 = SupportWidth_cm1(keep);
            ResolutionRatio = ResolutionRatio(keep);
            
            DerivativeZ = DerivativeZ(keep);
            ResidualZ = ResidualZ(keep);
            NeighborDiscordance = NeighborDiscordance(keep);
            Score = Score(keep);
            
            Source = Source(keep);
            
            Accepted = Accepted(keep);
            Reviewed = Reviewed(keep);
            Corrected = Corrected(keep);
            
            % =============================================================
            % CREATE TABLE DIRECTLY
            %
            % No cell2table.
            % No cell2mat.
            % =============================================================
            
            T = table( ...
                ID, ...
                Y, ...
                X, ...
                KSeedStart, ...
                KSeedEnd, ...
                KStart, ...
                KPeak, ...
                KPeak2, ...
                KEnd, ...
                WN, ...
                WN2, ...
                EventType, ...
                FWHM_cm1, ...
                SupportWidth_cm1, ...
                ResolutionRatio, ...
                DerivativeZ, ...
                ResidualZ, ...
                NeighborDiscordance, ...
                Score, ...
                Source, ...
                Accepted, ...
                Reviewed, ...
                Corrected);
            
            % =============================================================
            % SORT BY EVIDENCE
            %
            % NaN neighbor scores are placed last.
            % =============================================================
            
            sortableNeighbor = ...
                T.NeighborDiscordance;
            
            sortableNeighbor(~isfinite(sortableNeighbor)) = ...
                -Inf;
            
            sortingMatrix = ...
                [T.Score, sortableNeighbor];
            
            [~, order] = ...
                sortrows( ...
                sortingMatrix, ...
                [-1 -2]);
            
            T = ...
                T(order, :);
            
            T.ID = ...
                (1:height(T))';
        end


        function T = makeEmptyCosmicCandidateTable(app)
            
            T = table( ...
                zeros(0,1), ...
                zeros(0,1), ...
                zeros(0,1), ...
                zeros(0,1), ...
                zeros(0,1), ...
                zeros(0,1), ...
                zeros(0,1), ...
                zeros(0,1), ...
                zeros(0,1), ...
                zeros(0,1), ...
                zeros(0,1), ...
                strings(0,1), ...
                zeros(0,1), ...
                zeros(0,1), ...
                zeros(0,1), ...
                zeros(0,1), ...
                zeros(0,1), ...
                zeros(0,1), ...
                zeros(0,1), ...
                strings(0,1), ...
                false(0,1), ...
                false(0,1), ...
                false(0,1), ...
                'VariableNames', { ...
                'ID', ...
                'Y', ...
                'X', ...
                'KSeedStart', ...
                'KSeedEnd', ...
                'KStart', ...
                'KPeak', ...
                'KPeak2', ...
                'KEnd', ...
                'WN', ...
                'WN2', ...
                'EventType', ...
                'FWHM_cm1', ...
                'SupportWidth_cm1', ...
                'ResolutionRatio', ...
                'DerivativeZ', ...
                'ResidualZ', ...
                'NeighborDiscordance', ...
                'Score', ...
                'Source', ...
                'Accepted', ...
                'Reviewed', ...
                'Corrected'});
        end
        
        
        function value = safeWNValue(app, indexValue)
            
            if isempty(indexValue) || ...
                    ~isfinite(indexValue) || ...
                    indexValue < 1 || ...
                    indexValue > numel(app.Wavenumber)
                
                value = ...
                    NaN;
                
                return
            end
            
            value = ...
                app.Wavenumber(round(indexValue));
        end
        
        
        function [peakLocations, peakValues] = ...
                findEventLocalPeaks(app, residualLine, kStart, kEnd)
            
            segment = ...
                double(residualLine(kStart:kEnd));
            
            if numel(segment) < 3
                
                [peakValue, localIndex] = ...
                    max(segment);
                
                peakLocations = ...
                    kStart + localIndex - 1;
                
                peakValues = ...
                    peakValue;
                
                return
            end
            
            localMax = ...
                false(size(segment));
            
            localMax(2:end-1) = ...
                segment(2:end-1) >= segment(1:end-2) & ...
                segment(2:end-1) >= segment(3:end) & ...
                segment(2:end-1) > 0;
            
            localIndices = ...
                find(localMax);
            
            if isempty(localIndices)
                
                [peakValue, localIndex] = ...
                    max(segment);
                
                peakLocations = ...
                    kStart + localIndex - 1;
                
                peakValues = ...
                    peakValue;
                
                return
            end
            
            maxValue = ...
                max(segment);
            
            keep = ...
                segment(localIndices) >= 0.20 * max(maxValue, eps);
            
            localIndices = ...
                localIndices(keep);
            
            peakLocations = ...
                kStart + localIndices - 1;
            
            peakValues = ...
                segment(localIndices);
        end
        
        
        function discordance = ...
                computeSpatialNeighborDiscordance(app, y, x, kPeak)
            
            cube = ...
                app.CubeOriginal;
            
            [ny, nx, ~] = ...
                size(cube);
            
            y1 = ...
                max(1, y - 1);
            
            y2 = ...
                min(ny, y + 1);
            
            x1 = ...
                max(1, x - 1);
            
            x2 = ...
                min(nx, x + 1);
            
            neighborValues = [];
            
            for yy = y1:y2
                for xx = x1:x2
                    
                    if yy == y && xx == x
                        continue
                    end
                    
                    if ~isempty(app.DeletedSpectrumMask2D) && ...
                            app.DeletedSpectrumMask2D(yy, xx)
                        
                        continue
                    end
                    
                    value = ...
                        cube(yy, xx, kPeak);
                    
                    if isfinite(value)
                        neighborValues(end + 1, 1) = value; %#ok<AGROW>
                    end
                end
            end
            
            currentValue = ...
                cube(y, x, kPeak);
            
            if isempty(neighborValues)
                
                discordance = ...
                    NaN;
                
                return
            end
            
            medValue = ...
                median(neighborValues, 'omitnan');
            
            sigmaValue = ...
                app.robustSigmaSingle(neighborValues);
            
            discordance = ...
                abs(currentValue - medValue) / ...
                max(sigmaValue, eps);
        end


        function qc = computeCosmicCorrectionQC( ...
                app, idx, beforeSpectrum, afterSpectrum)
            
            T = ...
                app.CandidateTable;
            
            kStart = ...
                T.KStart(idx);
            
            kEnd = ...
                T.KEnd(idx);
            
            target = ...
                (kStart:kEnd)';
            
            leftIndex = ...
                max(1, kStart - 1);
            
            rightIndex = ...
                min(numel(beforeSpectrum), kEnd + 1);
            
            beforeBaseline = ...
                movmedian(beforeSpectrum, 9, 'omitnan');
            
            afterBaseline = ...
                movmedian(afterSpectrum, 9, 'omitnan');
            
            beforeResidual = ...
                beforeSpectrum(target) - ...
                beforeBaseline(target);
            
            afterResidual = ...
                afterSpectrum(target) - ...
                afterBaseline(target);
            
            beforeEnergy = ...
                sum(beforeResidual .^ 2, 'omitnan');
            
            afterEnergy = ...
                sum(afterResidual .^ 2, 'omitnan');
            
            residualReduction = ...
                100 * (beforeEnergy - afterEnergy) / ...
                max(beforeEnergy, eps);
            
            peakBefore = ...
                max(beforeSpectrum(target), [], 'omitnan');
            
            peakAfter = ...
                max(afterSpectrum(target), [], 'omitnan');
            
            localReference = ...
                median( ...
                beforeSpectrum( ...
                max(1, kStart - app.CorrectParams.SupportPoints): ...
                min(numel(beforeSpectrum), ...
                kEnd + app.CorrectParams.SupportPoints)), ...
                'omitnan');
            
            peakSuppression = ...
                100 * ...
                ((peakBefore - localReference) - ...
                (peakAfter - localReference)) / ...
                max(abs(peakBefore - localReference), eps);
            
            leftMismatch = ...
                abs(afterSpectrum(kStart) - ...
                afterSpectrum(leftIndex));
            
            rightMismatch = ...
                abs(afterSpectrum(kEnd) - ...
                afterSpectrum(rightIndex));
            
            localScale = ...
                app.robustSigmaSingle( ...
                beforeSpectrum( ...
                max(1, kStart - 20): ...
                min(numel(beforeSpectrum), kEnd + 20)));
            
            edgeMismatchSigma = ...
                max(leftMismatch, rightMismatch) / ...
                max(localScale, eps);
            
            localBeforeMin = ...
                min(beforeSpectrum( ...
                max(1, kStart - app.CorrectParams.SupportPoints): ...
                min(numel(beforeSpectrum), ...
                kEnd + app.CorrectParams.SupportPoints)), ...
                [], ...
                'omitnan');
            
            localBeforeMax = ...
                max(beforeSpectrum( ...
                max(1, kStart - app.CorrectParams.SupportPoints): ...
                min(numel(beforeSpectrum), ...
                kEnd + app.CorrectParams.SupportPoints)), ...
                [], ...
                'omitnan');
            
            overshoot = ...
                any(afterSpectrum(target) < ...
                localBeforeMin - 0.25 * abs(localBeforeMax - localBeforeMin)) || ...
                any(afterSpectrum(target) > ...
                localBeforeMax + 0.25 * abs(localBeforeMax - localBeforeMin));
            
            neighborBefore = ...
                app.computeSpectrumNeighborDeviation( ...
                T.Y(idx), ...
                T.X(idx), ...
                target, ...
                beforeSpectrum);
            
            neighborAfter = ...
                app.computeSpectrumNeighborDeviation( ...
                T.Y(idx), ...
                T.X(idx), ...
                target, ...
                afterSpectrum);
            
            if residualReduction >= 70 && ...
                    edgeMismatchSigma <= 3 && ...
                    neighborAfter <= max(2.5, neighborBefore) && ...
                    ~overshoot
                
                decision = ...
                    "PASS";
                
            elseif residualReduction >= 40 && ...
                    edgeMismatchSigma <= 5 && ...
                    ~overshoot
                
                decision = ...
                    "REVIEW";
                
            else
                
                decision = ...
                    "FAIL";
            end
            
            qc = struct();
            
            qc.ResidualReductionPercent = ...
                residualReduction;
            
            qc.PeakSuppressionPercent = ...
                peakSuppression;
            
            qc.EdgeMismatchSigma = ...
                edgeMismatchSigma;
            
            qc.NeighborDeviationBefore = ...
                neighborBefore;
            
            qc.NeighborDeviationAfter = ...
                neighborAfter;
            
            qc.Overshoot = ...
                overshoot;
            
            qc.ReplacedPoints = ...
                numel(target);
            
            qc.Decision = ...
                decision;
        end
        
        
        function deviation = computeSpectrumNeighborDeviation( ...
                app, y, x, targetIndices, candidateSpectrum)
            
            cube = ...
                app.CubeOriginal;
            
            [ny, nx, ~] = ...
                size(cube);
            
            neighborSpectra = [];
            
            for yy = max(1, y - 1):min(ny, y + 1)
                for xx = max(1, x - 1):min(nx, x + 1)
                    
                    if yy == y && xx == x
                        continue
                    end
                    
                    if ~isempty(app.DeletedSpectrumMask2D) && ...
                            app.DeletedSpectrumMask2D(yy, xx)
                        
                        continue
                    end
                    
                    spec = ...
                        double(squeeze(cube(yy, xx, :)));
                    
                    if all(isfinite(spec(targetIndices)))
                        
                        neighborSpectra(:, end + 1) = ...
                            spec(targetIndices); %#ok<AGROW>
                    end
                end
            end
            
            if isempty(neighborSpectra)
                
                deviation = ...
                    NaN;
                
                return
            end
            
            neighborMedian = ...
                median(neighborSpectra, 2, 'omitnan');
            
            neighborDeviation = ...
                abs(neighborSpectra - neighborMedian);
            
            scale = ...
                median(neighborDeviation(:), 'omitnan');
            
            deviation = ...
                median( ...
                abs(candidateSpectrum(targetIndices) - ...
                neighborMedian), ...
                'omitnan') / ...
                max(scale, eps);
        end
        
        
        function showCosmicCorrectionQC(app, idx, qc)
            
            T = ...
                app.CandidateTable;
            
            if qc.Overshoot
                overshootText = 'YES';
            else
                overshootText = 'NONE';
            end
            
            app.TextArea.Value = { ...
                'CORRECTION QC'; ...
                '================================'; ...
                sprintf('Candidate: %03d | %s', ...
                idx, char(T.EventType(idx))); ...
                sprintf('Pixel: Y=%d, X=%d', ...
                T.Y(idx), T.X(idx)); ...
                sprintf('Range: %.2f to %.2f cm^{-1}', ...
                app.Wavenumber(T.KStart(idx)), ...
                app.Wavenumber(T.KEnd(idx))); ...
                sprintf('FWHM: %.3f cm^{-1} | %.2fR', ...
                T.FWHM_cm1(idx), ...
                T.ResolutionRatio(idx)); ...
                ''; ...
                sprintf('Method: %s | %s', ...
                char(app.CorrectParams.Method), ...
                char(app.CorrectParams.Severity)); ...
                ''; ...
                sprintf('Residual reduction: %.1f%%', ...
                qc.ResidualReductionPercent); ...
                sprintf('Peak suppression: %.1f%%', ...
                qc.PeakSuppressionPercent); ...
                sprintf('Edge mismatch: %.2f sigma', ...
                qc.EdgeMismatchSigma); ...
                sprintf('Neighbor deviation before: %.2f sigma', ...
                qc.NeighborDeviationBefore); ...
                sprintf('Neighbor deviation after: %.2f sigma', ...
                qc.NeighborDeviationAfter); ...
                sprintf('Replaced points: %d', ...
                qc.ReplacedPoints); ...
                ['Overshoot: ' overshootText]; ...
                ''; ...
                ['QC DECISION: ' char(qc.Decision)]};
        end


        function proceed = chooseCosmicSpatialScope(app)
            
            proceed = false;
            
            if isempty(app.CubeOriginal)
                
                uialert( ...
                    app.UIFigure, ...
                    'Load a Raman scan first.', ...
                    'No Raman scan');
                
                return
            end
            
            [ny, nx, ~] = ...
                size(app.CubeOriginal);
            
            finiteMask = ...
                all(isfinite(app.CubeOriginal), 3);
            
            hasExistingROI = ...
                app.ROIReady && ...
                ~isempty(app.CurrentROIMask) && ...
                isequal(size(app.CurrentROIMask), [ny nx]) && ...
                any(app.CurrentROIMask(:));
            
            if hasExistingROI
                
                choice = ...
                    uiconfirm( ...
                    app.UIFigure, ...
                    { ...
                    'Choose the spatial scope for cosmic detection:'; ...
                    ''; ...
                    sprintf('Existing ROI: %d / %d valid pixels', ...
                    nnz(app.CurrentROIMask & finiteMask), ...
                    nnz(finiteMask)); ...
                    ''; ...
                    'Use existing ROI:'; ...
                    '  Detect only inside the current ROI.'; ...
                    ''; ...
                    'Redraw ROI:'; ...
                    '  Replace the current ROI with a new polygon selection.'; ...
                    ''; ...
                    'Use full valid scan:'; ...
                    '  Detect in every finite valid spectrum.'}, ...
                    'Cosmic spatial scope', ...
                    'Options', { ...
                    'Use existing ROI', ...
                    'Redraw ROI', ...
                    'Use full valid scan', ...
                    'Cancel'}, ...
                    'DefaultOption', ...
                    'Use existing ROI', ...
                    'CancelOption', ...
                    'Cancel');
                
            else
                
                choice = ...
                    uiconfirm( ...
                    app.UIFigure, ...
                    { ...
                    'Choose the spatial scope for cosmic detection:'; ...
                    ''; ...
                    'Draw ROI:'; ...
                    '  Restrict detection to selected cells or regions.'; ...
                    '  This generally reduces false candidates and review work.'; ...
                    ''; ...
                    'Use full valid scan:'; ...
                    '  Detect in every finite valid spectrum.'}, ...
                    'Cosmic spatial scope', ...
                    'Options', { ...
                    'Draw ROI', ...
                    'Use full valid scan', ...
                    'Cancel'}, ...
                    'DefaultOption', ...
                    'Draw ROI', ...
                    'CancelOption', ...
                    'Cancel');
            end
            
            if isempty(choice) || ...
                    strcmp(choice, 'Cancel')
                
                return
            end
            
            if strcmp(choice, 'Draw ROI') || ...
                    strcmp(choice, 'Redraw ROI')
                
                oldMask = ...
                    app.CurrentROIMask;
                
                oldReady = ...
                    app.ROIReady;
                
                app.drawMultiROI();
                
                % drawMultiROI uses full scan when no polygon is completed.
                % Restore previous ROI if the drawing was effectively cancelled.
                if isempty(app.CurrentROIMask) || ...
                        ~isequal(size(app.CurrentROIMask), [ny nx]) || ...
                        ~any(app.CurrentROIMask(:))
                    
                    app.CurrentROIMask = ...
                        oldMask;
                    
                    app.ROIReady = ...
                        oldReady;
                    
                    return
                end
                
                app.CurrentROIMask = ...
                    logical(app.CurrentROIMask) & finiteMask;
                
            elseif strcmp(choice, 'Use full valid scan')
                
                app.CurrentROIMask = ...
                    finiteMask;
                
                app.ROIReady = ...
                    false;
                
            elseif strcmp(choice, 'Use existing ROI')
                
                app.CurrentROIMask = ...
                    logical(app.CurrentROIMask) & finiteMask;
            end
            
            if ~any(app.CurrentROIMask(:))
                
                uialert( ...
                    app.UIFigure, ...
                    'The selected spatial scope contains no valid spectrum.', ...
                    'Empty spatial scope');
                
                return
            end
            
            app.updateScanInformationBox();
            app.plotRawMap();
            
            proceed = true;
        end


        function T = mergeAutomaticAndManualCandidates( ...
                app, ...
                automaticTable, ...
                manualTable)
            
            if isempty(automaticTable) && ...
                    isempty(manualTable)
                
                T = ...
                    app.makeEmptyCosmicCandidateTable();
                
                return
            end
            
            if isempty(automaticTable)
                
                T = ...
                    app.ensureCosmicCandidateTableSchema( ...
                    manualTable);
                
                T.ID = ...
                    (1:height(T))';
                
                return
            end
            
            if isempty(manualTable)
                
                T = ...
                    app.ensureCosmicCandidateTableSchema( ...
                    automaticTable);
                
                T.ID = ...
                    (1:height(T))';
                
                return
            end
            
            automaticTable = ...
                app.ensureCosmicCandidateTableSchema( ...
                automaticTable);
            
            manualTable = ...
                app.ensureCosmicCandidateTableSchema( ...
                manualTable);
            
            automaticTable.Source(:) = ...
                "Automatic";
            
            manualTable.Source(:) = ...
                "Manual";
            
            keepManual = ...
                true(height(manualTable), 1);
            
            % Remove a manual event only when it overlaps an automatic event
            % at the same spatial pixel.
            for m = 1:height(manualTable)
                
                samePixel = ...
                    automaticTable.Y == manualTable.Y(m) & ...
                    automaticTable.X == manualTable.X(m);
                
                spectralOverlap = ...
                    automaticTable.KStart <= manualTable.KEnd(m) & ...
                    automaticTable.KEnd >= manualTable.KStart(m);
                
                if any(samePixel & spectralOverlap)
                    
                    keepManual(m) = ...
                        false;
                end
            end
            
            manualTable = ...
                manualTable(keepManual, :);
            
            % Align variable order before vertical concatenation.
            variableOrder = ...
                automaticTable.Properties.VariableNames;
            
            manualTable = ...
                manualTable(:, variableOrder);
            
            T = ...
                [automaticTable; manualTable];
            
            T.ID = ...
                (1:height(T))';
        end
        
        
        function folderPath = getCurrentCosmicDataFolder(app)
            
            app.initializeProjectPaths();
            
            folderPath = ...
                app.Paths.CosmicDataRoot;
            
            if isempty(folderPath)
                
                error( ...
                    'Cosmic data folder cannot be resolved.');
            end
            
            if ~isfolder(folderPath)
                mkdir(folderPath);
            end
            
        end


        function nodes = findAllTreeNodes(app, nodeList)
            
            nodes = ...
                matlab.ui.container.TreeNode.empty;
            
            if isempty(nodeList)
                return
            end
            
            for i = 1:numel(nodeList)
                
                node = ...
                    nodeList(i);
                
                nodes(end + 1) = ...
                    node; %#ok<AGROW>
                
                if ~isempty(node.Children)
                    
                    childNodes = ...
                        app.findAllTreeNodes( ...
                        node.Children);
                    
                    nodes = ...
                        [nodes childNodes]; %#ok<AGROW>
                end
            end
        end
        
        
        function [T, addedCount, duplicateCount] = ...
                appendManualCandidatesPreserveReview( ...
                app, ...
                existingTable, ...
                newManualTable)
            
            addedCount = ...
                0;
            
            duplicateCount = ...
                0;
            
            % =============================================================
            % 1. NORMALIZE EXISTING TABLE
            % =============================================================
            
            if isempty(existingTable)
                
                existingTable = ...
                    app.makeEmptyCosmicCandidateTable();
                
            else
                
                existingTable = ...
                    app.ensureCosmicCandidateTableSchema( ...
                    existingTable);
            end
            
            % =============================================================
            % 2. NORMALIZE NEW TABLE
            % =============================================================
            
            if isempty(newManualTable)
                
                T = ...
                    existingTable;
                
                if ~isempty(T)
                    T.ID = ...
                        (1:height(T))';
                end
                
                return
            end
            
            newManualTable = ...
                app.ensureCosmicCandidateTableSchema( ...
                newManualTable);
            
            newManualTable.Source(:) = ...
                "Manual";
            
            newManualTable.Accepted(:) = ...
                false;
            
            newManualTable.Reviewed(:) = ...
                false;
            
            newManualTable.Corrected(:) = ...
                false;
            
            % =============================================================
            % 3. ALIGN VARIABLES
            % =============================================================
            
            if isempty(existingTable)
                
                T = ...
                    newManualTable;
                
                T.ID = ...
                    (1:height(T))';
                
                addedCount = ...
                    height(T);
                
                return
            end
            
            existingNames = ...
                existingTable.Properties.VariableNames;
            
            newNames = ...
                newManualTable.Properties.VariableNames;
            
            missingInNew = ...
                setdiff( ...
                existingNames, ...
                newNames, ...
                'stable');
            
            for k = 1:numel(missingInNew)
                
                variableName = ...
                    missingInNew{k};
                
                templateValue = ...
                    existingTable.(variableName);
                
                nNew = ...
                    height(newManualTable);
                
                if isstring(templateValue)
                    
                    newManualTable.(variableName) = ...
                        strings(nNew, 1);
                    
                elseif islogical(templateValue)
                    
                    newManualTable.(variableName) = ...
                        false(nNew, 1);
                    
                elseif isnumeric(templateValue)
                    
                    newManualTable.(variableName) = ...
                        nan(nNew, 1);
                    
                elseif iscell(templateValue)
                    
                    newManualTable.(variableName) = ...
                        cell(nNew, 1);
                    
                else
                    
                    newManualTable.(variableName) = ...
                        repmat( ...
                        templateValue(1), ...
                        nNew, ...
                        1);
                end
            end
            
            extraInNew = ...
                setdiff( ...
                newManualTable.Properties.VariableNames, ...
                existingNames, ...
                'stable');
            
            if ~isempty(extraInNew)
                
                newManualTable(:, extraInNew) = ...
                    [];
            end
            
            newManualTable = ...
                newManualTable(:, existingNames);
            
            % =============================================================
            % 4. REMOVE DUPLICATES AGAINST EXISTING TABLE
            %
            % Duplicate:
            %   same Y/X pixel
            %   AND overlapping KStart-KEnd interval.
            % =============================================================
            
            keepNew = ...
                true(height(newManualTable), 1);
            
            for m = 1:height(newManualTable)
                
                samePixel = ...
                    existingTable.Y == ...
                    newManualTable.Y(m) & ...
                    existingTable.X == ...
                    newManualTable.X(m);
                
                overlappingRange = ...
                    existingTable.KStart <= ...
                    newManualTable.KEnd(m) & ...
                    existingTable.KEnd >= ...
                    newManualTable.KStart(m);
                
                if any(samePixel & overlappingRange)
                    
                    keepNew(m) = ...
                        false;
                    
                    duplicateCount = ...
                        duplicateCount + 1;
                end
            end
            
            newManualTable = ...
                newManualTable(keepNew, :);
            
            % =============================================================
            % 5. REMOVE DUPLICATES INSIDE NEW TABLE
            % =============================================================
            
            if height(newManualTable) > 1
                
                keepWithinNew = ...
                    true(height(newManualTable), 1);
                
                for m = 1:height(newManualTable)
                    
                    if ~keepWithinNew(m)
                        continue
                    end
                    
                    laterIdx = ...
                        (m + 1):height(newManualTable);
                    
                    if isempty(laterIdx)
                        continue
                    end
                    
                    samePixel = ...
                        newManualTable.Y(laterIdx) == ...
                        newManualTable.Y(m) & ...
                        newManualTable.X(laterIdx) == ...
                        newManualTable.X(m);
                    
                    overlappingRange = ...
                        newManualTable.KStart(laterIdx) <= ...
                        newManualTable.KEnd(m) & ...
                        newManualTable.KEnd(laterIdx) >= ...
                        newManualTable.KStart(m);
                    
                    duplicateLater = ...
                        laterIdx(samePixel & overlappingRange);
                    
                    if ~isempty(duplicateLater)
                        
                        keepWithinNew(duplicateLater) = ...
                            false;
                        
                        duplicateCount = ...
                            duplicateCount + ...
                            numel(duplicateLater);
                    end
                end
                
                newManualTable = ...
                    newManualTable(keepWithinNew, :);
            end
            
            % =============================================================
            % 6. APPEND
            % =============================================================
            
            addedCount = ...
                height(newManualTable);
            
            if addedCount == 0
                
                T = ...
                    existingTable;
                
            else
                
                T = ...
                    [existingTable; newManualTable];
            end
            
            T.ID = ...
                (1:height(T))';
        end
        
        function runCosmicRedetectionOnStoredROIs( ...
                app, ...
                params, ...
                selectedMode)
            
            % =============================================================
            % RE-DETECT COSMIC EVENTS INSIDE STORED MANUAL ROIs
            %
            % IMPORTANT BEHAVIOR
            %   - Preserve all existing candidates.
            %   - Preserve Accepted / Reviewed / Corrected decisions.
            %   - Preserve the existing CorrectedCube.
            %   - Exclude spectra that already contain accepted candidates.
            %   - Append only new, non-duplicate candidates.
            %   - New candidates are marked Source = "Manual".
            % =============================================================
            
            progressDialog = [];
            
            % Variables used for guaranteed restoration.
            oldSpatialMask = [];
            oldROIReady = false;
            oldWNMask = [];
            oldWNMode = '';
            
            scopeWasChanged = false;
            
            try
                
                % =============================================================
                % 1. VALIDATE CURRENT SCAN
                % =============================================================
                
                if isempty(app.CubeOriginal)
                    
                    uialert( ...
                        app.UIFigure, ...
                        'Load a Raman scan first.', ...
                        'No Raman scan');
                    
                    return
                end
                
                [ny, nx, nWn] = ...
                    size(app.CubeOriginal);
                
                % =============================================================
                % 2. VALIDATE STORED MANUAL ROI
                % =============================================================
                
                validStoredROI = ...
                    app.ManualCosmicROIReady && ...
                    ~isempty(app.ManualCosmicSpatialMask) && ...
                    ~isempty(app.ManualCosmicWNMask) && ...
                    isequal( ...
                    size(app.ManualCosmicSpatialMask), ...
                    [ny nx]) && ...
                    numel(app.ManualCosmicWNMask) == nWn && ...
                    any(app.ManualCosmicSpatialMask(:)) && ...
                    any(app.ManualCosmicWNMask(:));
                
                if ~validStoredROI
                    
                    app.ManualCosmicROIReady = ...
                        false;
                    
                    uialert( ...
                        app.UIFigure, ...
                        { ...
                        'No valid stored manual cosmic ROI is available.'; ...
                        ''; ...
                        'Press Manual detect and define one or more'; ...
                        'spectral ROIs first.'}, ...
                        'No stored manual ROI');
                    
                    return
                end
                
                % Normalize input parameters.
                params = ...
                    app.sanitizeDetectionParams( ...
                    params);
                
                if nargin < 3 || ...
                        isempty(selectedMode)
                    
                    selectedMode = ...
                        char(string(params.Mode));
                else
                    
                    selectedMode = ...
                        char(string(selectedMode));
                end
                
                params.Mode = ...
                    string(selectedMode);
                
                % =============================================================
                % 3. PRESERVE EXISTING CANDIDATE TABLE
                % =============================================================
                
                existingTable = ...
                    app.CandidateTable;
                
                if isempty(existingTable)
                    
                    existingTable = ...
                        app.makeEmptyCosmicCandidateTable();
                    
                else
                    
                    existingTable = ...
                        app.ensureCosmicCandidateTableSchema( ...
                        existingTable);
                end
                
                oldCandidateCount = ...
                    height(existingTable);
                
                oldAutomaticCount = ...
                    0;
                
                oldManualCount = ...
                    0;
                
                oldAcceptedCount = ...
                    0;
                
                oldReviewedCount = ...
                    0;
                
                oldCorrectedCount = ...
                    0;
                
                if ~isempty(existingTable)
                    
                    oldAutomaticCount = ...
                        nnz( ...
                        ~strcmpi( ...
                        string(existingTable.Source), ...
                        "Manual"));
                    
                    oldManualCount = ...
                        nnz( ...
                        strcmpi( ...
                        string(existingTable.Source), ...
                        "Manual"));
                    
                    oldAcceptedCount = ...
                        nnz(existingTable.Accepted);
                    
                    oldReviewedCount = ...
                        nnz(existingTable.Reviewed);
                    
                    oldCorrectedCount = ...
                        nnz(existingTable.Corrected);
                end
                
                % =============================================================
                % 4. EXCLUDE SPECTRA WITH ACCEPTED CANDIDATES
                %
                % A complete spatial spectrum is skipped when at least one
                % candidate in that spectrum was already accepted.
                % =============================================================
                
                acceptedSpatialMask = ...
                    false(ny, nx);
                
                if ~isempty(existingTable)
                    
                    acceptedIndices = ...
                        find(existingTable.Accepted);
                    
                    for q = 1:numel(acceptedIndices)
                        
                        i = ...
                            acceptedIndices(q);
                        
                        y = ...
                            round(double(existingTable.Y(i)));
                        
                        x = ...
                            round(double(existingTable.X(i)));
                        
                        if y >= 1 && ...
                                y <= ny && ...
                                x >= 1 && ...
                                x <= nx
                            
                            acceptedSpatialMask(y, x) = ...
                                true;
                        end
                    end
                end
                
                storedSpatialMask = ...
                    logical(app.ManualCosmicSpatialMask);
                
                storedWNMask = ...
                    logical(app.ManualCosmicWNMask(:));
                
                detectionSpatialMask = ...
                    storedSpatialMask & ...
                    ~acceptedSpatialMask;
                
                selectedSpatialCount = ...
                    nnz(storedSpatialMask);
                
                skippedAcceptedSpatialCount = ...
                    nnz( ...
                    storedSpatialMask & ...
                    acceptedSpatialMask);
                
                detectionSpatialCount = ...
                    nnz(detectionSpatialMask);
                
                selectedWNCount = ...
                    nnz(storedWNMask);
                
                if detectionSpatialCount == 0
                    
                    uialert( ...
                        app.UIFigure, ...
                        { ...
                        'Nothing remains to re-detect.'; ...
                        ''; ...
                        'Every spectrum inside the stored manual ROI'; ...
                        'already contains at least one accepted candidate.'; ...
                        ''; ...
                        sprintf('Stored ROI spectra: %d', ...
                        selectedSpatialCount); ...
                        sprintf('Accepted spectra skipped: %d', ...
                        skippedAcceptedSpatialCount)}, ...
                        'Nothing to re-detect');
                    
                    return
                end
                
                if selectedWNCount == 0
                    
                    uialert( ...
                        app.UIFigure, ...
                        'The stored manual ROI contains no valid wavenumber point.', ...
                        'Empty spectral ROI');
                    
                    return
                end
                
                % =============================================================
                % 5. PRESERVE NORMAL DETECTION SCOPE
                % =============================================================
                
                oldSpatialMask = ...
                    app.CurrentROIMask;
                
                oldROIReady = ...
                    app.ROIReady;
                
                oldWNMask = ...
                    app.CosmicWNMask;
                
                oldWNMode = ...
                    app.CosmicWNMode;
                
                % =============================================================
                % 6. APPLY STORED ROI SCOPE TEMPORARILY
                % =============================================================
                
                app.CurrentROIMask = ...
                    detectionSpatialMask;
                
                app.ROIReady = ...
                    true;
                
                app.CosmicWNMask = ...
                    storedWNMask;
                
                app.CosmicWNMode = ...
                    sprintf( ...
                    'Stored manual ROI re-detection | regions=%d', ...
                    app.ManualCosmicROICount);
                
                scopeWasChanged = ...
                    true;
                
                % =============================================================
                % 7. DISPLAY RUNNING STATUS
                % =============================================================
                
                statusLines = { ...
                    sprintf('Mode: %s', ...
                    selectedMode); ...
                    sprintf('Stored spectral ROI regions: %d', ...
                    app.ManualCosmicROICount); ...
                    sprintf('Stored ROI spectra: %d', ...
                    selectedSpatialCount); ...
                    sprintf('Spectra being re-detected: %d', ...
                    detectionSpatialCount); ...
                    sprintf('Accepted spectra skipped: %d', ...
                    skippedAcceptedSpatialCount); ...
                    sprintf('WN points being re-detected: %d / %d', ...
                    selectedWNCount, ...
                    nWn); ...
                    ''; ...
                    sprintf('Existing automatic candidates retained: %d', ...
                    oldAutomaticCount); ...
                    sprintf('Existing manual candidates retained: %d', ...
                    oldManualCount); ...
                    sprintf('Existing accepted decisions retained: %d', ...
                    oldAcceptedCount); ...
                    sprintf('Existing corrected candidates retained: %d', ...
                    oldCorrectedCount); ...
                    ''; ...
                    'Re-detection is running only inside stored ROIs.'; ...
                    'The existing corrected cube will not be erased.'};
                
                if ~isempty(app.ManualCosmicROISummary)
                    
                    statusLines = ...
                        [ ...
                        statusLines; ...
                        {''}; ...
                        app.ManualCosmicROISummary(:) ...
                        ];
                end
                
                app.setText( ...
                    app.makeCosmicWorkflowStatusText( ...
                    'Re-detecting cosmic spikes on selected ROIs', ...
                    statusLines));
                
                drawnow;
                
                progressDialog = ...
                    uiprogressdlg( ...
                    app.UIFigure, ...
                    'Title', ...
                    'Re-detecting cosmic on ROIs', ...
                    'Message', ...
                    sprintf( ...
                    ['Mode: %s\n' ...
                    'ROIs: %d | spectra: %d | WN points: %d'], ...
                    selectedMode, ...
                    app.ManualCosmicROICount, ...
                    detectionSpatialCount, ...
                    selectedWNCount), ...
                    'Indeterminate', ...
                    'on');
                
                % =============================================================
                % 8. CHOOSE DETECTION CUBE
                %
                % Use the current active cube. Therefore, if old candidates were
                % already corrected, the ROI detector examines the corrected cube.
                % The CorrectedCube itself is not modified here.
                % =============================================================
                
                cubeForDetection = ...
                    app.getActiveCube();
                
                % =============================================================
                % 9. RUN ROI-ONLY DETECTION
                % =============================================================
                
                [~, ~, newCandidates] = ...
                    app.detectCosmicDerivative( ...
                    cubeForDetection, ...
                    params);
                
                if ~isempty(progressDialog)
                    
                    try
                        
                        if isvalid(progressDialog)
                            close(progressDialog);
                        end
                        
                    catch
                    end
                end
                
                progressDialog = [];
                
                % =============================================================
                % 10. RESTORE NORMAL SCOPE IMMEDIATELY
                % =============================================================
                
                app.CurrentROIMask = ...
                    oldSpatialMask;
                
                app.ROIReady = ...
                    oldROIReady;
                
                app.CosmicWNMask = ...
                    oldWNMask;
                
                app.CosmicWNMode = ...
                    oldWNMode;
                
                scopeWasChanged = ...
                    false;
                
                % =============================================================
                % 11. NORMALIZE NEW CANDIDATES
                % =============================================================
                
                if isempty(newCandidates)
                    
                    newCandidates = ...
                        app.makeEmptyCosmicCandidateTable();
                    
                else
                    
                    newCandidates = ...
                        app.ensureCosmicCandidateTableSchema( ...
                        newCandidates);
                    
                    newCandidates.Source(:) = ...
                        "Manual";
                    
                    newCandidates.Accepted(:) = ...
                        false;
                    
                    newCandidates.Reviewed(:) = ...
                        false;
                    
                    newCandidates.Corrected(:) = ...
                        false;
                end
                
                detectedBeforeDuplicateRemoval = ...
                    height(newCandidates);
                
                % =============================================================
                % 12. APPEND WITHOUT ALTERING EXISTING ROWS
                %
                % appendManualCandidatesPreserveReview must:
                %   - retain existing rows unchanged;
                %   - remove spatial/spectral duplicates;
                %   - append only genuinely new rows.
                % =============================================================
                
                [combinedTable, addedCount, duplicateCount] = ...
                    app.appendManualCandidatesPreserveReview( ...
                    existingTable, ...
                    newCandidates);
                
                combinedTable = ...
                    app.ensureCosmicCandidateTableSchema( ...
                    combinedTable);
                
                % =============================================================
                % 13. DEFENSIVE PRESERVATION CHECK
                %
                % Restore the old review values explicitly for all original rows.
                % This prevents any schema/append helper from accidentally
                % changing old decisions.
                % =============================================================
                
                preservedOldRows = ...
                    min( ...
                    oldCandidateCount, ...
                    height(combinedTable));
                
                if preservedOldRows > 0
                    
                    combinedTable.Source(1:preservedOldRows) = ...
                        existingTable.Source(1:preservedOldRows);
                    
                    combinedTable.Accepted(1:preservedOldRows) = ...
                        existingTable.Accepted(1:preservedOldRows);
                    
                    combinedTable.Reviewed(1:preservedOldRows) = ...
                        existingTable.Reviewed(1:preservedOldRows);
                    
                    combinedTable.Corrected(1:preservedOldRows) = ...
                        existingTable.Corrected(1:preservedOldRows);
                end
                
                % Ensure all appended rows are pending manual candidates.
                if height(combinedTable) > oldCandidateCount
                    
                    newRowIndices = ...
                        (oldCandidateCount + 1):height(combinedTable);
                    
                    combinedTable.Source(newRowIndices) = ...
                        "Manual";
                    
                    combinedTable.Accepted(newRowIndices) = ...
                        false;
                    
                    combinedTable.Reviewed(newRowIndices) = ...
                        false;
                    
                    combinedTable.Corrected(newRowIndices) = ...
                        false;
                end
                
                combinedTable.ID = ...
                    (1:height(combinedTable))';
                
                app.CandidateTable = ...
                    combinedTable;
                
                % =============================================================
                % 14. REBUILD SUMMARY AND CONFIRMED MASK
                % =============================================================
                
                app.CandidateSummary = ...
                    app.computeCandidateSummary();
                
                app.ConfirmedMask = ...
                    app.buildMaskFromAcceptedCandidates();
                
                % There may be accepted candidates from the previous run.
                app.CosmicConfirmed = ...
                    any(app.CandidateTable.Accepted);
                
                % New ROI candidates are pending, so final review must reopen.
                app.CosmicReviewFinalized = ...
                    false;
                
                % =============================================================
                % 15. PRESERVE PREVIOUS CORRECTION RESULT
                %
                % Do not clear CorrectedCube.
                % Do not reset old Corrected values.
                % =============================================================
                
                app.CorrectionReady = ...
                    ~isempty(app.CorrectedCube) || ...
                    any(app.CandidateTable.Corrected);
                
                app.CorrectionTrialMode = ...
                    false;
                
                app.LastTrialCandidateIndex = ...
                    NaN;
                
                app.LastTrialBeforeSpectrum = ...
                    [];
                
                app.LastTrialAfterSpectrum = ...
                    [];
                
                app.LastTrialQC = ...
                    struct();
                
                % =============================================================
                % 16. REBUILD TREE
                %
                % Current page is reset to automatic Page 1.
                % The previous automatic list remains because its rows were
                % retained at the beginning of CandidateTable.
                % =============================================================
                
                app.CurrentCandidateIndex = ...
                    NaN;
                
                app.CurrentCandidatePage = ...
                    1;
                
                app.initializeCandidatePaging();
                
                app.populateCandidateTree(false);
                
                app.setCosmicWorkflowControls( ...
                    'detected');
                
                % =============================================================
                % 17. VERIFY PRESERVATION COUNTS
                % =============================================================
                
                finalAutomaticCount = ...
                    nnz( ...
                    ~strcmpi( ...
                    string(app.CandidateTable.Source), ...
                    "Manual"));
                
                finalManualCount = ...
                    nnz( ...
                    strcmpi( ...
                    string(app.CandidateTable.Source), ...
                    "Manual"));
                
                finalAcceptedCount = ...
                    nnz(app.CandidateTable.Accepted);
                
                finalReviewedCount = ...
                    nnz(app.CandidateTable.Reviewed);
                
                finalCorrectedCount = ...
                    nnz(app.CandidateTable.Corrected);
                
                % =============================================================
                % 18. DISPLAY RESULT
                % =============================================================
                
                if addedCount > 0
                    
                    firstNewIndex = ...
                        oldCandidateCount + 1;
                    
                    if firstNewIndex <= height(app.CandidateTable)
                        
                        app.CurrentCandidateIndex = ...
                            firstNewIndex;
                        
                        app.selectCandidateNode( ...
                            firstNewIndex);
                        
                        app.displayCandidate( ...
                            firstNewIndex);
                    else
                        
                        app.plotCandidateSummaryView();
                    end
                    
                elseif ~isempty(app.CandidateTable)
                    
                    % No new candidate was added, but preserve and show the
                    % complete candidate summary.
                    app.plotCandidateSummaryView();
                    
                else
                    
                    app.plotRawMap();
                end
                
                % =============================================================
                % 19. FINAL STATUS
                % =============================================================
                
                resultLines = { ...
                    sprintf('Mode used: %s', ...
                    selectedMode); ...
                    sprintf('Stored ROI regions: %d', ...
                    app.ManualCosmicROICount); ...
                    sprintf('Stored ROI spectra: %d', ...
                    selectedSpatialCount); ...
                    sprintf('Spectra searched: %d', ...
                    detectionSpatialCount); ...
                    sprintf('Accepted spectra skipped: %d', ...
                    skippedAcceptedSpatialCount); ...
                    sprintf('WN points searched: %d / %d', ...
                    selectedWNCount, ...
                    nWn); ...
                    ''; ...
                    sprintf('ROI candidates detected before duplicate removal: %d', ...
                    detectedBeforeDuplicateRemoval); ...
                    sprintf('New manual candidates added: %d', ...
                    addedCount); ...
                    sprintf('Duplicate candidates skipped: %d', ...
                    duplicateCount); ...
                    ''; ...
                    sprintf('Total candidates now: %d', ...
                    height(app.CandidateTable)); ...
                    sprintf('Automatic candidates retained: %d', ...
                    finalAutomaticCount); ...
                    sprintf('Manual candidates now: %d', ...
                    finalManualCount); ...
                    sprintf('Accepted candidates retained: %d', ...
                    finalAcceptedCount); ...
                    sprintf('Reviewed candidates retained: %d', ...
                    finalReviewedCount); ...
                    sprintf('Corrected candidates retained: %d', ...
                    finalCorrectedCount); ...
                    sprintf('Pending candidates now: %d', ...
                    nnz(~app.CandidateTable.Reviewed)); ...
                    ''; ...
                    'Automatic Page 1 and its previous decisions were retained.'; ...
                    'The previously corrected cube was retained.'; ...
                    'New ROI candidates are listed as Manual and Pending.'; ...
                    ''; ...
                    'The stored ROI remains available.'; ...
                    'Change Mode and press Cosmic detection again'; ...
                    'to re-detect these same ROIs.'};
                
                app.setText( ...
                    app.makeCosmicWorkflowStatusText( ...
                    'ROI cosmic re-detection complete', ...
                    resultLines));
                
                app.updateScanInformationBox();
                
            catch ME
                
                % =============================================================
                % RESTORE UI DIALOG
                % =============================================================
                
                if ~isempty(progressDialog)
                    
                    try
                        
                        if isvalid(progressDialog)
                            close(progressDialog);
                        end
                        
                    catch
                    end
                end
                
                % =============================================================
                % RESTORE NORMAL DETECTION SCOPE AFTER FAILURE
                % =============================================================
                
                if scopeWasChanged
                    
                    app.CurrentROIMask = ...
                        oldSpatialMask;
                    
                    app.ROIReady = ...
                        oldROIReady;
                    
                    app.CosmicWNMask = ...
                        oldWNMask;
                    
                    app.CosmicWNMode = ...
                        oldWNMode;
                end
                
                diagnosticText = ...
                    getReport( ...
                    ME, ...
                    'extended', ...
                    'hyperlinks', ...
                    'off');
                
                disp(diagnosticText);
                
                if isempty(ME.stack)
                    
                    locationText = ...
                        'Location unavailable';
                    
                else
                    
                    locationText = ...
                        sprintf( ...
                        '%s, line %d', ...
                        ME.stack(1).name, ...
                        ME.stack(1).line);
                end
                
                uialert( ...
                    app.UIFigure, ...
                    sprintf( ...
                    '%s\n\nLocation: %s', ...
                    ME.message, ...
                    locationText), ...
                    'ROI cosmic re-detection error');
            end
        end
        
        
        function cube = validateCosmicToArtifactTransfer(app)
            
            if isempty(app.CubeOriginal)
                
                error( ...
                    'CubeOriginal is empty.');
            end
            
            cube = ...
                app.getCommittedCube();
            
            if isempty(cube)
                
                error( ...
                    'No committed cube is available for Artifact.');
            end
            
            if ~isequal( ...
                    size(cube), ...
                    size(app.CubeOriginal))
                
                error( ...
                    ['Committed cube size does not match CubeOriginal. ' ...
                    'Artifact transfer was blocked.']);
            end
            
            if numel(app.Wavenumber) ~= ...
                    size(cube, 3)
                
                error( ...
                    ['Wavenumber length does not match the committed cube. ' ...
                    'Artifact transfer was blocked.']);
            end
            
            finiteFraction = ...
                nnz(isfinite(cube)) / ...
                numel(cube);
            
            if finiteFraction < 1
                
                warning( ...
                    'RamanCube:NonfiniteCommittedCube', ...
                    'Committed cube contains non-finite values.');
            end
            
            if app.CorrectionTrialMode && ...
                    ~isempty(app.TrialCorrectedCube)
                
                app.setArtifactText({ ...
                    'COSMIC TO ARTIFACT TRANSFER'; ...
                    '================================'; ...
                    ''; ...
                    'A trial preview is currently active.'; ...
                    'Artifact will NOT receive TrialCorrectedCube.'; ...
                    ''; ...
                    'Artifact input:'; ...
                    '  committed CorrectedCube when available,'; ...
                    '  otherwise CubeOriginal.'});
            end
        end


        function plotAllUsableSpectraBeforeAfterReview(app)
            
            if isempty(app.CubeOriginal)
                
                uialert( ...
                    app.UIFigure, ...
                    'No Raman scan is loaded.', ...
                    'No Raman scan');
                
                return
            end
            
            [ny, nx, nWn] = ...
                size(app.CubeOriginal);
            
            usableMask = ...
                app.getCurrentUsableSpectrumMask();
            
            if isempty(usableMask) || ...
                    ~isequal(size(usableMask), [ny nx])
                
                usableMask = ...
                    all(isfinite(app.CubeOriginal), 3);
            end
            
            usableMask = ...
                logical(usableMask);
            
            usablePixelIdx = ...
                find(usableMask);
            
            if isempty(usablePixelIdx)
                
                uialert( ...
                    app.UIFigure, ...
                    'No usable spectrum is available.', ...
                    'No usable spectrum');
                
                return
            end
            
            plotPixelIdx = ...
                usablePixelIdx;
            
            sampled = ...
                false;
            
            if numel(plotPixelIdx) > app.MaxReviewPlotSpectra
                
                rng(1);
                
                plotPixelIdx = ...
                    plotPixelIdx( ...
                    randperm( ...
                    numel(plotPixelIdx), ...
                    app.MaxReviewPlotSpectra));
                
                sampled = ...
                    true;
            end
            
            XRaw = ...
                reshape( ...
                app.CubeOriginal, ...
                ny * nx, ...
                nWn);
            
            currentCube = ...
                app.getActiveCube();
            
            XCurrent = ...
                reshape( ...
                currentCube, ...
                ny * nx, ...
                nWn);
            
            if app.CorrectionTrialMode && ...
                    ~isempty(app.TrialCorrectedCube)
                
                currentLabel = ...
                    'TRIAL corrected spectra';
                
            elseif ~isempty(app.CorrectedCube)
                
                currentLabel = ...
                    'COMMITTED corrected spectra';
                
            else
                
                currentLabel = ...
                    'Current spectra — no correction committed';
            end
            
            % =============================================================
            % AXIS 1: RAW SPECTRA
            % =============================================================
            
            app.resetSpectrumAxis( ...
                app.UIAxes);
            
            hold(app.UIAxes, 'on');
            
            rawValues = ...
                [];
            
            for p = 1:numel(plotPixelIdx)
                
                spectrum = ...
                    double(XRaw(plotPixelIdx(p), :));
                
                plot( ...
                    app.UIAxes, ...
                    app.Wavenumber, ...
                    spectrum, ...
                    'Color', ...
                    [0.78 0.78 0.78], ...
                    'LineWidth', ...
                    0.25);
                
                rawValues = ...
                    [rawValues; spectrum(:)]; %#ok<AGROW>
            end
            
            rawMedian = ...
                median( ...
                XRaw(plotPixelIdx, :), ...
                1, ...
                'omitnan');
            
            plot( ...
                app.UIAxes, ...
                app.Wavenumber, ...
                rawMedian, ...
                'k-', ...
                'LineWidth', ...
                1.8);
            
            hold(app.UIAxes, 'off');
            
            title( ...
                app.UIAxes, ...
                sprintf( ...
                'RAW usable spectra | shown = %d / %d', ...
                numel(plotPixelIdx), ...
                numel(usablePixelIdx)), ...
                'Interpreter', ...
                'none');
            
            app.setNiceSpectralLimits( ...
                app.UIAxes, ...
                app.Wavenumber, ...
                rawValues);
            
            app.addCosmicWNFocusOverlay( ...
                app.UIAxes);
            
            % =============================================================
            % AXIS 2: TRIAL OR COMMITTED DATA
            % =============================================================
            
            app.resetSpectrumAxis( ...
                app.UIAxes2);
            
            hold(app.UIAxes2, 'on');
            
            currentValues = ...
                [];
            
            for p = 1:numel(plotPixelIdx)
                
                spectrum = ...
                    double(XCurrent(plotPixelIdx(p), :));
                
                plot( ...
                    app.UIAxes2, ...
                    app.Wavenumber, ...
                    spectrum, ...
                    'Color', ...
                    [0.30 0.30 0.82], ...
                    'LineWidth', ...
                    0.25);
                
                currentValues = ...
                    [currentValues; spectrum(:)]; %#ok<AGROW>
            end
            
            currentMedian = ...
                median( ...
                XCurrent(plotPixelIdx, :), ...
                1, ...
                'omitnan');
            
            plot( ...
                app.UIAxes2, ...
                app.Wavenumber, ...
                currentMedian, ...
                'b-', ...
                'LineWidth', ...
                1.8);
            
            hold(app.UIAxes2, 'off');
            
            title( ...
                app.UIAxes2, ...
                sprintf( ...
                '%s | shown = %d / %d', ...
                currentLabel, ...
                numel(plotPixelIdx), ...
                numel(usablePixelIdx)), ...
                'Interpreter', ...
                'none');
            
            app.setNiceSpectralLimits( ...
                app.UIAxes2, ...
                app.Wavenumber, ...
                currentValues);
            
            app.addCosmicWNFocusOverlay( ...
                app.UIAxes2);
            
            if sampled
                
                samplingText = ...
                    sprintf( ...
                    'Reproducible sample plotted: %d / %d spectra.', ...
                    numel(plotPixelIdx), ...
                    numel(usablePixelIdx));
                
            else
                
                samplingText = ...
                    'All usable spectra are plotted.';
            end
            
            app.setText({ ...
                'WHOLE-SCAN COSMIC REVIEW'; ...
                '================================'; ...
                ''; ...
                sprintf('Usable spectra: %d', ...
                numel(usablePixelIdx)); ...
                sprintf('Displayed spectra: %d', ...
                numel(plotPixelIdx)); ...
                samplingText; ...
                ''; ...
                'Axis 1:'; ...
                '  Raw usable spectra.'; ...
                '  Black line = raw median.'; ...
                ''; ...
                'Axis 2:'; ...
                ['  ' currentLabel '.']; ...
                '  Blue line = current median.'; ...
                ''; ...
                'WN scope overlay:'; ...
                '  Normal background = selected cosmic WN focus.'; ...
                '  Gray shaded regions = excluded from primary detection.'; ...
                '  Red dashed boundaries = auxiliary 2000–2750 region.'; ...
                ''; ...
                sprintf('Auxiliary corrected events: %d', ...
                app.AuxCosmicCorrectedCount); ...
                ''; ...
                'This view checks whether obvious spikes remain anywhere'; ...
                'in the usable scan, not only inside manual ROIs.'});
        end
        
        

        function [cubeOut, info] = ...
                autoCorrectAuxiliaryCosmicRegion( ...
                app, ...
                cubeIn)
            
            cubeOut = ...
                double(cubeIn);
            
            info = struct();
            info.Enabled = false;
            info.Range = double(app.AuxCosmicRange);
            info.DetectedCount = 0;
            info.CorrectedCount = 0;
            info.Mask = logical.empty;
            info.CandidateTable = table();
            
            if ~app.AuxCosmicEnabled || ...
                    isempty(cubeIn) || ...
                    isempty(app.Wavenumber)
                
                return
            end
            
            [ny, nx, nWn] = ...
                size(cubeOut);
            
            wn = ...
                double(app.Wavenumber(:));
            
            if numel(wn) ~= nWn
                return
            end
            
            lowWN = ...
                min(double(app.AuxCosmicRange));
            
            highWN = ...
                max(double(app.AuxCosmicRange));
            
            fullAuxiliaryMask = ...
                wn >= lowWN & ...
                wn <= highWN;
            
            if nnz(fullAuxiliaryMask) < 3
                return
            end
            
            % =============================================================
            % ONLY CLEAN THE PART OUTSIDE THE USER'S PRIMARY WN FOCUS
            % =============================================================
            
            if ~isempty(app.CosmicWNMask) && ...
                    numel(app.CosmicWNMask) == nWn
                
                primaryFocusMask = ...
                    logical(app.CosmicWNMask(:));
                
            else
                
                primaryFocusMask = ...
                    true(nWn, 1);
            end
            
            missingAuxiliaryMask = ...
                fullAuxiliaryMask & ...
                ~primaryFocusMask;
            
            if ~any(missingAuxiliaryMask)
                return
            end
            
            % =============================================================
            % PRESERVE CURRENT APP SCOPE
            % =============================================================
            
            oldSpatialMask = ...
                app.CurrentROIMask;
            
            oldROIReady = ...
                app.ROIReady;
            
            oldWNMask = ...
                app.CosmicWNMask;
            
            oldWNMode = ...
                app.CosmicWNMode;
            
            try
                
                % Use the normal usable spatial scope.
                usableSpatialMask = ...
                    app.getCurrentUsableSpectrumMask();
                
                if isempty(usableSpatialMask) || ...
                        ~isequal(size(usableSpatialMask), [ny nx])
                    
                    usableSpatialMask = ...
                        all(isfinite(cubeOut), 3);
                end
                
                app.CurrentROIMask = ...
                    logical(usableSpatialMask);
                
                app.ROIReady = ...
                    true;
                
                app.CosmicWNMask = ...
                    logical(missingAuxiliaryMask);
                
                app.CosmicWNMode = ...
                    sprintf( ...
                    'Auxiliary auto-correction %.0f–%.0f cm^{-1}', ...
                    lowWN, ...
                    highWN);
                
                auxParams = ...
                    app.getCosmicDetectionPresetParams( ...
                    'Medium');
                
                auxParams.Mode = ...
                    "Auxiliary Medium";
                
                auxParams.Method = ...
                    "Derivative + local residual";
                
                [auxMask, ~, auxTable] = ...
                    app.detectCosmicDerivative( ...
                    cubeOut, ...
                    auxParams);
                
                % Restore normal scope immediately after detection.
                app.CurrentROIMask = ...
                    oldSpatialMask;
                
                app.ROIReady = ...
                    oldROIReady;
                
                app.CosmicWNMask = ...
                    oldWNMask;
                
                app.CosmicWNMode = ...
                    oldWNMode;
                
                info.Enabled = ...
                    true;
                
                info.Mask = ...
                    logical(auxMask);
                
                if isempty(auxTable)
                    
                    app.AuxCosmicMask = ...
                        logical(auxMask);
                    
                    app.AuxCosmicCandidateTable = ...
                        app.makeEmptyCosmicCandidateTable();
                    
                    app.AuxCosmicCorrectedCount = ...
                        0;
                    
                    info.CandidateTable = ...
                        app.AuxCosmicCandidateTable;
                    
                    return
                end
                
                auxTable = ...
                    app.ensureCosmicCandidateTableSchema( ...
                    auxTable);
                
                auxTable.Source(:) = ...
                    "Auxiliary 2000-2750";
                
                auxTable.Accepted(:) = ...
                    true;
                
                auxTable.Reviewed(:) = ...
                    true;
                
                auxTable.Corrected(:) = ...
                    false;
                
                auxCorrectParams = ...
                    app.getCosmicCorrectionPresetParams( ...
                    'Medium', ...
                    'PCHIP interpolation');
                
                correctedCount = ...
                    0;
                
                for i = 1:height(auxTable)
                    
                    y = ...
                        round(double(auxTable.Y(i)));
                    
                    x = ...
                        round(double(auxTable.X(i)));
                    
                    if y < 1 || ...
                            y > ny || ...
                            x < 1 || ...
                            x > nx
                        
                        continue
                    end
                    
                    spectrum = ...
                        double(squeeze( ...
                        cubeOut(y, x, :)));
                    
                    correctedSpectrum = ...
                        app.correctSingleSpectrum( ...
                        spectrum, ...
                        auxTable.KStart(i), ...
                        auxTable.KEnd(i), ...
                        auxCorrectParams);
                    
                    cubeOut(y, x, :) = ...
                        reshape( ...
                        correctedSpectrum, ...
                        1, 1, []);
                    
                    auxTable.Corrected(i) = ...
                        true;
                    
                    correctedCount = ...
                        correctedCount + 1;
                end
                
                app.AuxCosmicMask = ...
                    logical(auxMask);
                
                app.AuxCosmicCandidateTable = ...
                    auxTable;
                
                app.AuxCosmicCorrectedCount = ...
                    correctedCount;
                
                info.DetectedCount = ...
                    height(auxTable);
                
                info.CorrectedCount = ...
                    correctedCount;
                
                info.CandidateTable = ...
                    auxTable;
                
            catch ME
                
                app.CurrentROIMask = ...
                    oldSpatialMask;
                
                app.ROIReady = ...
                    oldROIReady;
                
                app.CosmicWNMask = ...
                    oldWNMask;
                
                app.CosmicWNMode = ...
                    oldWNMode;
                
                rethrow(ME);
            end
        end


        function openDeleteSpectrumReviewer(app)
            
            % =============================================================
            % DELETE / RESTORE SPECTRUM REVIEWER
            %
            % GUI behavior:
            %   - Table contains all finite spectra.
            %   - Selecting one row displays that spectrum.
            %   - The mean of all currently active spectra is displayed together.
            %   - Delete/restore is mask-based; cube dimensions are unchanged.
            %   - CurrentROIMask is never modified.
            % =============================================================
            
            cube = ...
                app.getActiveCube();
            
            if isempty(cube) || ...
                    ndims(cube) ~= 3
                
                uialert( ...
                    app.UIFigure, ...
                    'No valid Raman cube is available.', ...
                    'Delete spectra');
                
                return
            end
            
            [ny, nx, nWn] = ...
                size(cube);
            
            wn = ...
                double(app.Wavenumber(:));
            
            if isempty(wn) || ...
                    numel(wn) ~= nWn
                
                uialert( ...
                    app.UIFigure, ...
                    'Wavenumber does not match the Raman cube.', ...
                    'Delete spectra');
                
                return
            end
            
            % =============================================================
            % 1. BUILD SPECTRUM MATRIX
            % =============================================================
            
            finiteMask = ...
                all(isfinite(cube), 3);
            
            pixelIndices = ...
                find(finiteMask);
            
            if isempty(pixelIndices)
                
                uialert( ...
                    app.UIFigure, ...
                    'No finite spectrum is available.', ...
                    'Delete spectra');
                
                return
            end
            
            if isempty(app.DeletedSpectrumMask2D) || ...
                    ~isequal( ...
                    size(app.DeletedSpectrumMask2D), ...
                    [ny nx])
                
                app.DeletedSpectrumMask2D = ...
                    false(ny, nx);
            end
            
            Xall = ...
                reshape( ...
                double(cube), ...
                ny * nx, ...
                nWn);
            
            X = ...
                Xall(pixelIndices, :);
            
            nSpectra = ...
                size(X, 1);
            
            % =============================================================
            % 2. COMPUTE TABLE METRICS — VECTORIZED
            % =============================================================
            
            finiteFraction = ...
                mean(isfinite(X), 2);
            
            medianAbsSignal = ...
                median( ...
                abs(X), ...
                2, ...
                'omitnan');
            
            roughnessNumerator = ...
                median( ...
                abs(diff(X, 1, 2)), ...
                2, ...
                'omitnan');
            
            roughness = ...
                roughnessNumerator ./ ...
                max(medianAbsSignal, eps);
            
            [yPos, xPos] = ...
                ind2sub( ...
                [ny nx], ...
                pixelIndices);
            
            deleted = ...
                logical( ...
                app.DeletedSpectrumMask2D(pixelIndices));
            
            inROI = ...
                false(nSpectra, 1);
            
            if ~isempty(app.CurrentROIMask) && ...
                    isequal( ...
                    size(app.CurrentROIMask), ...
                    [ny nx])
                
                inROI = ...
                    logical( ...
                    app.CurrentROIMask(pixelIndices));
            end
            
            reviewTable = ...
                table( ...
                pixelIndices, ...
                yPos, ...
                xPos, ...
                inROI, ...
                finiteFraction, ...
                medianAbsSignal, ...
                roughness, ...
                deleted, ...
                'VariableNames', { ...
                'LinearIndex', ...
                'Y', ...
                'X', ...
                'InROI', ...
                'FiniteFraction', ...
                'MedianAbsSignal', ...
                'Roughness', ...
                'Deleted'});
            
            % Most suspicious/rough spectra appear first.
            [~, order] = ...
                sort( ...
                reviewTable.Roughness, ...
                'descend', ...
                'MissingPlacement', ...
                'last');
            
            reviewTable = ...
                reviewTable(order, :);
            
            % Local row-to-spectrum mapping after sorting.
            sortedPixelIndices = ...
                reviewTable.LinearIndex;
            
            % =============================================================
            % 3. CREATE REVIEW GUI
            % =============================================================
            
            fig = ...
                uifigure( ...
                'Name', ...
                ['Delete spectra review - ' app.CurrentScanName], ...
                'Position', ...
                [70 70 1320 780], ...
                'Resize', ...
                'on');
            
            mainLayout = ...
                uigridlayout( ...
                fig, ...
                [2 3]);
            
            mainLayout.RowHeight = ...
                {'1x', 70};
            
            mainLayout.ColumnWidth = ...
                {460, '1x', 280};
            
            mainLayout.Padding = ...
                [10 10 10 10];
            
            mainLayout.RowSpacing = ...
                8;
            
            mainLayout.ColumnSpacing = ...
                8;
            
            % =============================================================
            % TABLE
            % =============================================================
            
            tbl = ...
                uitable(mainLayout);
            
            tbl.Layout.Row = ...
                1;
            
            tbl.Layout.Column = ...
                1;
            
            tbl.Data = ...
                reviewTable;
            
            tbl.ColumnEditable = ...
                false(1, width(reviewTable));
            
            tbl.Multiselect = ...
                'off';
            
            tbl.ColumnWidth = { ...
                80, ...
                45, ...
                45, ...
                50, ...
                90, ...
                105, ...
                90, ...
                60};
            
            tbl.SelectionChangedFcn = ...
                @tableSelectionChanged;
            
            % =============================================================
            % SPECTRUM AXES
            % =============================================================
            
            ax = ...
                uiaxes(mainLayout);
            
            ax.Layout.Row = ...
                1;
            
            ax.Layout.Column = ...
                2;
            
            ax.Box = ...
                'on';
            
            ax.XGrid = ...
                'off';
            
            ax.YGrid = ...
                'off';
            
            ax.YDir = ...
                'normal';
            
            ax.YAxisLocation = ...
                'right';
            
            xlabel( ...
                ax, ...
                'Wavenumber (cm^{-1})');
            
            ylabel( ...
                ax, ...
                'Intensity');
            
            % Create only three persistent line objects.
            hold(ax, 'on');
            
            meanLine = ...
                plot( ...
                ax, ...
                wn, ...
                nan(size(wn)), ...
                'Color', [0.25 0.25 0.25], ...
                'LineWidth', 1.8, ...
                'DisplayName', ...
                'Mean of active spectra');
            
            selectedLine = ...
                plot( ...
                ax, ...
                wn, ...
                nan(size(wn)), ...
                'b-', ...
                'LineWidth', 1.35, ...
                'DisplayName', ...
                'Selected spectrum');
            
            deletedSelectedLine = ...
                plot( ...
                ax, ...
                wn, ...
                nan(size(wn)), ...
                'r-', ...
                'LineWidth', 1.35, ...
                'DisplayName', ...
                'Selected deleted spectrum');
            
            hold(ax, 'off');
            
            legend( ...
                ax, ...
                'Location', ...
                'best');
            
            % =============================================================
            % INFORMATION AREA
            % =============================================================
            
            info = ...
                uitextarea(mainLayout);
            
            info.Layout.Row = ...
                1;
            
            info.Layout.Column = ...
                3;
            
            info.Editable = ...
                'off';
            
            info.Value = { ...
                'DELETE SPECTRA REVIEW'; ...
                '================================'; ...
                ''; ...
                sprintf('Total finite spectra: %d', nSpectra); ...
                sprintf('Currently deleted: %d', ...
                nnz(app.DeletedSpectrumMask2D)); ...
                ''; ...
                'Select one row in the table.'; ...
                ''; ...
                'Black: mean of all active spectra.'; ...
                'Blue: selected active spectrum.'; ...
                'Red: selected deleted spectrum.'; ...
                ''; ...
                'Delete/restore changes only'; ...
                'DeletedSpectrumMask2D.'; ...
                ''; ...
                'ROI and cube size are unchanged.'};
            
            % =============================================================
            % BUTTON PANEL
            % =============================================================
            
            buttonLayout = ...
                uigridlayout( ...
                mainLayout, ...
                [1 4]);
            
            buttonLayout.Layout.Row = ...
                2;
            
            buttonLayout.Layout.Column = ...
                [1 3];
            
            buttonLayout.ColumnWidth = ...
                {'1x', '1x', '1x', '1x'};
            
            deleteButton = ...
                uibutton( ...
                buttonLayout, ...
                'push', ...
                'Text', ...
                'Delete / exclude selected');
            
            restoreButton = ...
                uibutton( ...
                buttonLayout, ...
                'push', ...
                'Text', ...
                'Restore selected');
            
            refreshButton = ...
                uibutton( ...
                buttonLayout, ...
                'push', ...
                'Text', ...
                'Refresh mean');
            
            closeButton = ...
                uibutton( ...
                buttonLayout, ...
                'push', ...
                'Text', ...
                'Close');
            
            selectedTableRow = ...
                [];
            
            % =============================================================
            % CALLBACKS
            % =============================================================
            
            tbl.SelectionChangedFcn = ...
                @tableSelectionChanged;
            
            deleteButton.ButtonPushedFcn = ...
                @deleteSelectedSpectrum;
            
            restoreButton.ButtonPushedFcn = ...
                @restoreSelectedSpectrum;
            
            refreshButton.ButtonPushedFcn = ...
                @(~, ~) refreshMeanSpectrum();
            
            closeButton.ButtonPushedFcn = ...
                @(~, ~) delete(fig);
            
            % =============================================================
            % INITIAL DISPLAY
            % =============================================================
            
            refreshMeanSpectrum();
            
            if height(reviewTable) > 0
                
                selectedTableRow = ...
                    1;
                
                % UITable uses cell-selection indices: [row, column].
                tbl.Selection = ...
                    [1 1];
                
                displaySelectedRow();
            end
            
            % =============================================================
            % NESTED FUNCTIONS
            % =============================================================
            
            function tableSelectionChanged(~, event)
                
                if isempty(event.Selection)
                    
                    selectedTableRow = ...
                        [];
                    
                    return
                end
                
                selection = ...
                    double(event.Selection);
                
                % Cell selection is returned as an N-by-2 matrix:
                % column 1 = table row
                % column 2 = table column.
                selectedTableRow = ...
                    selection(1, 1);
                
                if selectedTableRow < 1 || ...
                        selectedTableRow > height(reviewTable)
                    
                    selectedTableRow = ...
                        [];
                    
                    return
                end
                
                displaySelectedRow();
            end
            
            function displaySelectedRow()
                
                if isempty(selectedTableRow) || ...
                        selectedTableRow < 1 || ...
                        selectedTableRow > height(reviewTable)
                    
                    return
                end
                
                linearIndex = ...
                    sortedPixelIndices(selectedTableRow);
                
                spectrum = ...
                    double(Xall(linearIndex, :));
                
                [y, x] = ...
                    ind2sub( ...
                    [ny nx], ...
                    linearIndex);
                
                isDeleted = ...
                    app.DeletedSpectrumMask2D(y, x);
                
                if isDeleted
                    
                    selectedLine.YData = ...
                        nan(size(wn));
                    
                    deletedSelectedLine.YData = ...
                        spectrum;
                    
                    selectedStatus = ...
                        'DELETED';
                    
                else
                    
                    selectedLine.YData = ...
                        spectrum;
                    
                    deletedSelectedLine.YData = ...
                        nan(size(wn));
                    
                    selectedStatus = ...
                        'ACTIVE';
                end
                
                finitePlotValues = ...
                    [ ...
                    meanLine.YData(:); ...
                    spectrum(:)];
                
                finitePlotValues = ...
                    finitePlotValues( ...
                    isfinite(finitePlotValues));
                
                if ~isempty(finitePlotValues)
                    
                    yLow = ...
                        prctile(finitePlotValues, 1);
                    
                    yHigh = ...
                        prctile(finitePlotValues, 99);
                    
                    yMin = ...
                        min(finitePlotValues);
                    
                    yMax = ...
                        max(finitePlotValues);
                    
                    yLow = ...
                        min(yLow, yMin);
                    
                    yHigh = ...
                        max(yHigh, yMax);
                    
                    if isfinite(yLow) && ...
                            isfinite(yHigh) && ...
                            yLow < yHigh
                        
                        pad = ...
                            0.06 * (yHigh - yLow);
                        
                        ylim( ...
                            ax, ...
                            [yLow - pad, ...
                            yHigh + pad]);
                    end
                end
                
                xlim( ...
                    ax, ...
                    [min(wn), max(wn)]);
                
                title( ...
                    ax, ...
                    { ...
                    sprintf( ...
                    'Selected spectrum | Y=%d, X=%d | %s', ...
                    y, x, selectedStatus); ...
                    'Black = active mean'}, ...
                    'Interpreter', ...
                    'none');
                
                info.Value = { ...
                    'SELECTED SPECTRUM'; ...
                    '================================'; ...
                    ''; ...
                    sprintf('Table row: %d', ...
                    selectedTableRow); ...
                    sprintf('Linear index: %d', ...
                    linearIndex); ...
                    sprintf('Y: %d', y); ...
                    sprintf('X: %d', x); ...
                    sprintf('Status: %s', ...
                    selectedStatus); ...
                    sprintf('Inside ROI: %d', ...
                    reviewTable.InROI(selectedTableRow)); ...
                    ''; ...
                    sprintf('Finite fraction: %.4f', ...
                    reviewTable.FiniteFraction(selectedTableRow)); ...
                    sprintf('Median abs signal: %.6g', ...
                    reviewTable.MedianAbsSignal(selectedTableRow)); ...
                    sprintf('Roughness: %.6g', ...
                    reviewTable.Roughness(selectedTableRow)); ...
                    ''; ...
                    sprintf('Total deleted: %d', ...
                    nnz(app.DeletedSpectrumMask2D))};
                
                drawnow limitrate;
            end
            
            function refreshMeanSpectrum()
                
                activeMask2D = ...
                    finiteMask & ...
                    ~app.DeletedSpectrumMask2D;
                
                activeIndices = ...
                    find(activeMask2D);
                
                if isempty(activeIndices)
                    
                    meanLine.YData = ...
                        nan(size(wn));
                    
                    return
                end
                
                % Use all active spectra as requested.
                activeMean = ...
                    mean( ...
                    Xall(activeIndices, :), ...
                    1, ...
                    'omitnan');
                
                meanLine.XData = ...
                    wn;
                
                meanLine.YData = ...
                    activeMean;
                
                drawnow limitrate;
            end
            
            function deleteSelectedSpectrum(~, ~)
                
                if isempty(selectedTableRow)
                    
                    uialert( ...
                        fig, ...
                        'Select one table row first.', ...
                        'No selected spectrum');
                    
                    return
                end
                
                linearIndex = ...
                    sortedPixelIndices(selectedTableRow);
                
                [y, x] = ...
                    ind2sub( ...
                    [ny nx], ...
                    linearIndex);
                
                if app.DeletedSpectrumMask2D(y, x)
                    
                    uialert( ...
                        fig, ...
                        'The selected spectrum is already deleted.', ...
                        'Already deleted');
                    
                    return
                end
                
                choice = ...
                    uiconfirm( ...
                    fig, ...
                    { ...
                    sprintf( ...
                    'Exclude spectrum Y=%d, X=%d?', ...
                    y, x); ...
                    ''; ...
                    'The cube size and ROI will not change.'}, ...
                    'Delete spectrum', ...
                    'Options', { ...
                    'Delete / exclude', ...
                    'Cancel'}, ...
                    'DefaultOption', ...
                    'Cancel', ...
                    'CancelOption', ...
                    'Cancel');
                
                if ~strcmp(choice, 'Delete / exclude')
                    return
                end
                
                app.DeletedSpectrumMask2D(y, x) = ...
                    true;
                
                reviewTable.Deleted(selectedTableRow) = ...
                    true;
                
                tbl.Data = ...
                    reviewTable;
                
                appendAuditRow( ...
                    y, ...
                    x, ...
                    linearIndex, ...
                    "Deleted");
                
                invalidateDownstreamResults();
                
                refreshMeanSpectrum();
                
                displaySelectedRow();
                
                app.updateScanInformationBox();
            end
            
            function restoreSelectedSpectrum(~, ~)
                
                if isempty(selectedTableRow)
                    
                    uialert( ...
                        fig, ...
                        'Select one table row first.', ...
                        'No selected spectrum');
                    
                    return
                end
                
                linearIndex = ...
                    sortedPixelIndices(selectedTableRow);
                
                [y, x] = ...
                    ind2sub( ...
                    [ny nx], ...
                    linearIndex);
                
                if ~app.DeletedSpectrumMask2D(y, x)
                    
                    uialert( ...
                        fig, ...
                        'The selected spectrum is already active.', ...
                        'Already active');
                    
                    return
                end
                
                choice = ...
                    uiconfirm( ...
                    fig, ...
                    sprintf( ...
                    'Restore spectrum Y=%d, X=%d?', ...
                    y, x), ...
                    'Restore spectrum', ...
                    'Options', { ...
                    'Restore', ...
                    'Cancel'}, ...
                    'DefaultOption', ...
                    'Restore', ...
                    'CancelOption', ...
                    'Cancel');
                
                if ~strcmp(choice, 'Restore')
                    return
                end
                
                app.DeletedSpectrumMask2D(y, x) = ...
                    false;
                
                reviewTable.Deleted(selectedTableRow) = ...
                    false;
                
                tbl.Data = ...
                    reviewTable;
                
                appendAuditRow( ...
                    y, ...
                    x, ...
                    linearIndex, ...
                    "Restored");
                
                invalidateDownstreamResults();
                
                refreshMeanSpectrum();
                
                displaySelectedRow();
                
                app.updateScanInformationBox();
            end
            
            function appendAuditRow(y, x, linearIndex, actionText)
                
                auditRow = ...
                    table( ...
                    string(datetime( ...
                    'now', ...
                    'Format', ...
                    'yyyy-MM-dd HH:mm:ss')), ...
                    y, ...
                    x, ...
                    linearIndex, ...
                    actionText, ...
                    'VariableNames', { ...
                    'Time', ...
                    'Y', ...
                    'X', ...
                    'LinearIndex', ...
                    'Action'});
                
                if isempty(app.DeletedSpectrumTable)
                    
                    app.DeletedSpectrumTable = ...
                        auditRow;
                    
                else
                    
                    app.DeletedSpectrumTable = ...
                        [ ...
                        app.DeletedSpectrumTable; ...
                        auditRow];
                end
            end
            
            function invalidateDownstreamResults()
                
                % Deleting/restoring a spectrum changes the usable population.
                % ROI itself remains untouched.
                
                app.CosmicConfirmed = ...
                    false;
                
                app.CosmicReviewFinalized = ...
                    false;
                
                app.ArtifactReady = ...
                    false;
                
                app.ArtifactConfirmed = ...
                    false;
                
                app.BackgroundReady = ...
                    false;
                
                app.BackgroundValidated = ...
                    false;
                
                app.BackgroundConfirmed = ...
                    false;
            end
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.initializeCosmicControls();
            
            if isempty(app.ROIDataMap) || ...
                    ~isa(app.ROIDataMap, 'containers.Map')
                
                app.ROIDataMap = ...
                    containers.Map( ...
                    'KeyType', 'char', ...
                    'ValueType', 'any');
            end
            
            try
                delete(app.Tree.Children);
            catch
            end
            
            app.clearAxes();
            app.setCosmicWorkflowControls('empty');
            
            app.TextArea.Value = { ...
                'SCAN PRETREAT'; ...
                '================================'; ...
                ''; ...
                'Waiting for Raman scan session and original scan data.'};
            
            app.TextArea_5.Value = { ...
                'No Raman scan loaded.'};
        end

        % Value changed function: ModesDropDown
        function ModesDropDownValueChanged(app, event)
            try
                
                selectedMode = ...
                    char(app.ModesDropDown.Value);
                
                oldMode = ...
                    app.CosmicDetectionMode;
                
                oldParams = ...
                    app.DetectionParams;
                
                % =========================================================
                % MANUAL
                % =========================================================
                
                if strcmp(selectedMode, 'Manual')
                    
                    % Start from current parameters so the user can refine the
                    % currently selected preset rather than returning to defaults.
                    manualParams = ...
                        app.editDetectionParametersManual( ...
                        app.DetectionParams);
                    
                    if isempty(manualParams)
                        
                        % User cancelled. Restore previous mode and parameters.
                        app.ModesDropDown.Value = ...
                            oldMode;
                        
                        app.DetectionParams = ...
                            oldParams;
                        
                        return
                    end
                    
                    manualParams.Mode = ...
                        "Manual";
                    
                    app.DetectionParams = ...
                        app.sanitizeDetectionParams(manualParams);
                    
                    app.CosmicDetectionMode = ...
                        'Manual';
                    
                else
                    
                    % =====================================================
                    % HEAVY / MEDIUM / LIGHT
                    % =====================================================
                    
                    presetParams = ...
                        app.getCosmicDetectionPresetParams( ...
                        selectedMode);
                    
                    presetParams.Mode = ...
                        string(selectedMode);
                    
                    app.DetectionParams = ...
                        presetParams;
                    
                    app.CosmicDetectionMode = ...
                        selectedMode;
                end
                
                % =========================================================
                % RESET RESULTS THAT DEPEND ON DETECTION PARAMETERS
                % =========================================================
                
                app.TempMask = ...
                    logical.empty;
                
                app.TempScore = ...
                    single.empty;
                
                app.ConfirmedMask = ...
                    logical.empty;
                
                app.CandidateTable = ...
                    table();
                
                app.CurrentCandidateIndex = ...
                    NaN;
                
                app.CurrentCandidatePage = ...
                    1;
                
                app.CandidateNumPages = ...
                    1;
                
                app.CandidateVisibleIndices = ...
                    [];
                
                app.CandidateSummary = ...
                    struct();
                
                app.CosmicConfirmed = ...
                    false;
                
                app.CosmicReviewFinalized = ...
                    false;
                
                app.CorrectionReady = ...
                    false;
                
                app.CorrectedCube = ...
                    [];
                
                % =========================================================
                % UI
                % =========================================================
                
                app.ModesDropDown.Tooltip = ...
                    app.getCosmicDetectionModeTooltip( ...
                    selectedMode);
                
                app.populateCandidateTree(false);
                
                if isempty(app.CubeOriginal)
                    app.setCosmicWorkflowControls('empty');
                else
                    app.setCosmicWorkflowControls('loaded');
                end
                
                app.setText( ...
                    app.makeCosmicDetectionModeText( ...
                    app.DetectionParams, ...
                    selectedMode));
                
            catch ME
                
                uialert( ...
                    app.UIFigure, ...
                    ME.message, ...
                    'Cosmic detection mode error');
            end
        end

        % Button pushed function: CosmicdetectionButton
        function CosmicdetectionButtonPushed(app, event)
            progressDialog = [];
            
            try
                
                % =============================================================
                % 1. VALIDATE SCAN
                % =============================================================
                
                if isempty(app.CubeOriginal)
                    
                    uialert( ...
                        app.UIFigure, ...
                        'Load a Raman scan first.', ...
                        'No Raman scan');
                    
                    return
                end
                
                [ny, nx, nWn] = ...
                    size(app.CubeOriginal);
                
                % =============================================================
                % 2. DETERMINE WHETHER STORED MANUAL ROIs ARE AVAILABLE
                % =============================================================
                
                hasStoredROI = ...
                    app.ManualCosmicROIReady && ...
                    ~isempty(app.ManualCosmicSpatialMask) && ...
                    ~isempty(app.ManualCosmicWNMask) && ...
                    isequal( ...
                    size(app.ManualCosmicSpatialMask), ...
                    [ny nx]) && ...
                    numel(app.ManualCosmicWNMask) == nWn && ...
                    any(app.ManualCosmicSpatialMask(:)) && ...
                    any(app.ManualCosmicWNMask(:));
                
                % =============================================================
                % 3. ASK DETECTION ACTION
                % =============================================================
                
                if hasStoredROI
                    
                    detectionAction = ...
                        uiconfirm( ...
                        app.UIFigure, ...
                        { ...
                        'Choose the cosmic detection action:'; ...
                        ''; ...
                        'New detection:'; ...
                        '  Run a complete new detection.'; ...
                        '  The current candidate list and review decisions'; ...
                        '  will be replaced.'; ...
                        ''; ...
                        'Re-detect selected ROIs:'; ...
                        sprintf('  Use %d stored manual ROI region(s).', ...
                        app.ManualCosmicROICount); ...
                        sprintf('  Search %d selected spatial spectra.', ...
                        nnz(app.ManualCosmicSpatialMask)); ...
                        sprintf('  Search %d selected WN points.', ...
                        nnz(app.ManualCosmicWNMask)); ...
                        '  Existing candidates and decisions are retained.'}, ...
                        'Cosmic detection action', ...
                        'Options', { ...
                        'New detection', ...
                        'Re-detect selected ROIs', ...
                        'Cancel'}, ...
                        'DefaultOption', ...
                        'Re-detect selected ROIs', ...
                        'CancelOption', ...
                        'Cancel');
                    
                else
                    
                    detectionAction = ...
                        uiconfirm( ...
                        app.UIFigure, ...
                        { ...
                        'No stored manual cosmic ROI is available.'; ...
                        ''; ...
                        'Run a new cosmic detection?'}, ...
                        'Cosmic detection action', ...
                        'Options', { ...
                        'New detection', ...
                        'Cancel'}, ...
                        'DefaultOption', ...
                        'New detection', ...
                        'CancelOption', ...
                        'Cancel');
                end
                
                if isempty(detectionAction) || ...
                        strcmp(detectionAction, 'Cancel')
                    
                    return
                end
                
                % =============================================================
                % 4. READ CURRENT MODE
                % =============================================================
                
                selectedMode = ...
                    char(string(app.ModesDropDown.Value));
                
                validModes = { ...
                    'Heavy', ...
                    'Medium', ...
                    'Light', ...
                    'Manual'};
                
                if ~any(strcmp(selectedMode, validModes))
                    
                    selectedMode = ...
                        'Medium';
                    
                    app.ModesDropDown.Value = ...
                        selectedMode;
                end
                
                if strcmp(selectedMode, 'Manual')
                    
                    params = ...
                        app.editDetectionParametersManual( ...
                        app.DetectionParams);
                    
                    if isempty(params)
                        return
                    end
                    
                else
                    
                    params = ...
                        app.getCosmicDetectionPresetParams( ...
                        selectedMode);
                end
                
                params = ...
                    app.sanitizeDetectionParams( ...
                    params);
                
                params.Mode = ...
                    string(selectedMode);
                
                app.DetectionParams = ...
                    params;
                
                app.CosmicDetectionMode = ...
                    selectedMode;
                
                % =============================================================
                % 5. RE-DETECT STORED ROIs
                % =============================================================
                
                if strcmp( ...
                        detectionAction, ...
                        'Re-detect selected ROIs')
                    
                    app.runCosmicRedetectionOnStoredROIs( ...
                        params, ...
                        selectedMode);
                    
                    return
                end
                
                % =============================================================
                % 6. NEW DETECTION CONFIRMATION
                % =============================================================
                
                if ~isempty(app.CandidateTable) && ...
                        height(app.CandidateTable) > 0
                    
                    replaceChoice = ...
                        uiconfirm( ...
                        app.UIFigure, ...
                        { ...
                        'New detection will replace the complete current'; ...
                        'candidate list and all review decisions.'; ...
                        ''; ...
                        sprintf('Current candidates: %d', ...
                        height(app.CandidateTable)); ...
                        sprintf('Current accepted: %d', ...
                        nnz(app.CandidateTable.Accepted)); ...
                        ''; ...
                        'Continue with a new detection?'}, ...
                        'Replace current cosmic detection', ...
                        'Options', { ...
                        'Replace and run', ...
                        'Cancel'}, ...
                        'DefaultOption', ...
                        'Cancel', ...
                        'CancelOption', ...
                        'Cancel');
                    
                    if ~strcmp( ...
                            replaceChoice, ...
                            'Replace and run')
                        
                        return
                    end
                end
                
                % =============================================================
                % 7. SELECT NORMAL SPATIAL SCOPE
                % =============================================================
                
                app.ensureROIForCurrentScan();
                
                usableMask = ...
                    app.getCurrentUsableSpectrumMask();
                
                if isempty(usableMask) || ...
                        ~any(usableMask(:))
                    
                    uialert( ...
                        app.UIFigure, ...
                        'The selected spatial scope contains no usable spectrum.', ...
                        'Empty detection scope');
                    
                    return
                end
                
                % =============================================================
                % 8. SELECT NORMAL WAVENUMBER SCOPE
                % =============================================================
                
                okWN = ...
                    app.chooseCosmicDetectionWNMask();
                
                if ~okWN
                    return
                end
                
                % =============================================================
                % 9. DISPLAY RUNNING STATUS
                % =============================================================
                
                app.setText( ...
                    app.makeCosmicWorkflowStatusText( ...
                    'Running new cosmic detection', ...
                    { ...
                    sprintf('Mode: %s', ...
                    selectedMode); ...
                    sprintf('Spatial spectra: %d', ...
                    nnz(usableMask)); ...
                    sprintf('WN mode: %s', ...
                    app.CosmicWNMode); ...
                    sprintf('WN points: %d / %d', ...
                    nnz(app.CosmicWNMask), ...
                    nWn); ...
                    ''; ...
                    'The current candidate list will be replaced.'}));
                
                drawnow;
                
                progressDialog = ...
                    uiprogressdlg( ...
                    app.UIFigure, ...
                    'Title', ...
                    'New cosmic detection', ...
                    'Message', ...
                    sprintf( ...
                    'Mode: %s | spectra: %d | WN points: %d', ...
                    selectedMode, ...
                    nnz(usableMask), ...
                    nnz(app.CosmicWNMask)), ...
                    'Indeterminate', ...
                    'on');
                
                % =============================================================
                % 10. RUN COMPLETE DETECTION
                % =============================================================
                
                cubeForDetection = ...
                    app.getActiveCube();
                
                [mask, scoreVolume, candidateTable] = ...
                    app.detectCosmicDerivative( ...
                    cubeForDetection, ...
                    params);
                
                if ~isempty(progressDialog) && ...
                        isvalid(progressDialog)
                    
                    close(progressDialog);
                end
                
                progressDialog = [];
                
                % =============================================================
                % 11. STORE COMPLETE NEW RESULT
                % =============================================================
                
                candidateTable = ...
                    app.ensureCosmicCandidateTableSchema( ...
                    candidateTable);
                
                if ~isempty(candidateTable)
                    
                    candidateTable.Source(:) = ...
                        "Automatic";
                    
                    candidateTable.Accepted(:) = ...
                        false;
                    
                    candidateTable.Reviewed(:) = ...
                        false;
                    
                    candidateTable.Corrected(:) = ...
                        false;
                end
                
                app.TempMask = ...
                    logical(mask);
                
                app.TempScore = ...
                    single(scoreVolume);
                
                app.CandidateTable = ...
                    candidateTable;
                
                app.ConfirmedMask = ...
                    logical.empty;
                
                app.CorrectedCube = ...
                    [];
                
                app.CosmicConfirmed = ...
                    false;
                
                app.CosmicReviewFinalized = ...
                    false;
                
                app.CorrectionReady = ...
                    false;
                
                app.CorrectionTrialMode = ...
                    false;
                
                app.CurrentCandidateIndex = ...
                    NaN;
                
                app.CurrentCandidatePage = ...
                    1;
                
                app.CandidateSummary = ...
                    app.computeCandidateSummary();
                
                app.initializeCandidatePaging();
                app.populateCandidateTree(false);
                
                app.setCosmicWorkflowControls('detected');
                
                % =============================================================
                % 12. DISPLAY RESULT
                % =============================================================
                
                if isempty(candidateTable)
                    
                    app.plotRawMap();
                    
                    app.setText( ...
                        app.makeCosmicWorkflowStatusText( ...
                        'New cosmic detection complete', ...
                        { ...
                        sprintf('Mode used: %s', ...
                        selectedMode); ...
                        sprintf('Spatial spectra searched: %d', ...
                        nnz(usableMask)); ...
                        sprintf('WN points searched: %d', ...
                        nnz(app.CosmicWNMask)); ...
                        ''; ...
                        'No cosmic candidate was detected.'; ...
                        ''; ...
                        'You may change Mode and run again.'}));
                    
                else
                    
                    firstIdx = ...
                        1;
                    
                    app.CurrentCandidateIndex = ...
                        firstIdx;
                    
                    app.selectCandidateNode( ...
                        firstIdx);
                    
                    app.displayCandidate( ...
                        firstIdx);
                    
                    app.setText( ...
                        app.makeCosmicWorkflowStatusText( ...
                        'New cosmic detection complete', ...
                        { ...
                        sprintf('Mode used: %s', ...
                        selectedMode); ...
                        sprintf('Total candidates: %d', ...
                        height(candidateTable)); ...
                        sprintf('Spatial spectra searched: %d', ...
                        nnz(usableMask)); ...
                        sprintf('WN points searched: %d', ...
                        nnz(app.CosmicWNMask)); ...
                        sprintf('Pages: %d', ...
                        app.CandidateNumPages); ...
                        ''; ...
                        'Candidate 1 is displayed for review.'}));
                end
                
                app.updateScanInformationBox();
                
            catch ME
                
                if ~isempty(progressDialog)
                    
                    try
                        if isvalid(progressDialog)
                            close(progressDialog);
                        end
                    catch
                    end
                end
                
                disp(getReport( ...
                    ME, ...
                    'extended', ...
                    'hyperlinks', ...
                    'off'));
                
                if isempty(ME.stack)
                    
                    locationText = ...
                        'Location unavailable';
                    
                else
                    
                    locationText = ...
                        sprintf( ...
                        '%s, line %d', ...
                        ME.stack(1).name, ...
                        ME.stack(1).line);
                end
                
                uialert( ...
                    app.UIFigure, ...
                    sprintf( ...
                    '%s\n\nLocation: %s', ...
                    ME.message, ...
                    locationText), ...
                    'Cosmic detection error');
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            % Agree
            app.applyCosmicDecision(true);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            % Disagree
            app.applyCosmicDecision(false);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            % Previous spectrum
            app.moveCosmicTreeSelection(-1);
        end

        % Button pushed function: ConfirmfinalcosmiclistButton
        function ConfirmfinalcosmiclistButtonPushed(app, event)
            if isempty(app.CandidateTable)
                
                uialert( ...
                    app.UIFigure, ...
                    'No cosmic candidate list is available.', ...
                    'No candidates');
                
                return
            end
            
            pendingCount = ...
                nnz(~app.CandidateTable.Reviewed);
            
            if pendingCount > 0
                
                choice = ...
                    uiconfirm( ...
                    app.UIFigure, ...
                    sprintf([ ...
                    '%d candidates are still pending.\n\n' ...
                    'Unreviewed candidates will be treated as ignored.\n' ...
                    'Continue?'], ...
                    pendingCount), ...
                    'Finalize cosmic list', ...
                    'Options', {'Finalize', 'Cancel'}, ...
                    'DefaultOption', 'Cancel', ...
                    'CancelOption', 'Cancel');
                
                if ~strcmp(choice, 'Finalize')
                    return
                end
            end
            
            app.CandidateTable.Accepted( ...
                ~app.CandidateTable.Reviewed) = ...
                false;
            
            app.CandidateTable.Reviewed( ...
                ~app.CandidateTable.Reviewed) = ...
                true;
            
            app.ConfirmedMask = ...
                app.buildMaskFromAcceptedCandidates();
            
            app.CosmicConfirmed = ...
                true;
            
            app.CosmicReviewFinalized = ...
                true;
            
            app.CandidateSummary = ...
                app.computeCandidateSummary();
            
            app.populateCandidateTree(true);
            
            app.setCosmicWorkflowControls('finalized');
            
            app.setText({ ...
                'FINAL COSMIC LIST CONFIRMED'; ...
                '================================'; ...
                sprintf('Total detected events: %d', ...
                height(app.CandidateTable)); ...
                sprintf('Accepted events: %d', ...
                nnz(app.CandidateTable.Accepted)); ...
                sprintf('Ignored events: %d', ...
                nnz(~app.CandidateTable.Accepted)); ...
                sprintf('Accepted spectra: %d', ...
                numel(unique( ...
                [app.CandidateTable.Y(app.CandidateTable.Accepted), ...
                app.CandidateTable.X(app.CandidateTable.Accepted)], ...
                'rows'))); ...
                ''; ...
                'Manual detection and review decisions are now locked.'; ...
                'Select an accepted candidate and run Trial correction.'});
        end

        % Value changed function: MethodsDropDown_2
        function MethodsDropDown_2ValueChanged(app, event)
            selectedMethod = ...
                char(string(app.MethodsDropDown_2.Value));
            
            selectedSeverity = ...
                char(string(app.ModesDropDown_2.Value));
            
            if isempty(selectedMethod)
                return
            end
            
            if isempty(selectedSeverity)
                
                selectedSeverity = ...
                    'Medium';
                
                app.ModesDropDown_2.Value = ...
                    selectedSeverity;
            end
            
            app.CorrectParams = ...
                app.getCosmicCorrectionPresetParams( ...
                selectedSeverity, ...
                selectedMethod);
            
            app.CorrectParamsChecked = ...
                true;
            
            app.CosmicCorrectionParameterMode = ...
                selectedSeverity;
            
            app.MethodsDropDown_2.Tooltip = ...
                app.getCorrectionMethodTooltip( ...
                selectedMethod);
            
            % Any previous trial is invalid after parameter changes.
            app.TrialCorrectedCube = ...
                [];
            
            app.CorrectionTrialMode = ...
                false;
            
            app.LastTrialCandidateIndex = ...
                NaN;
            
            app.LastTrialBeforeSpectrum = ...
                [];
            
            app.LastTrialAfterSpectrum = ...
                [];
            
            app.LastTrialQC = ...
                struct();
            
            app.setText({ ...
                'CORRECTION METHOD UPDATED'; ...
                '================================'; ...
                ''; ...
                ['Method: ' selectedMethod]; ...
                ['Severity: ' selectedSeverity]; ...
                sprintf('Expand points: %d', ...
                app.CorrectParams.ExpandPoints); ...
                sprintf('Support points per side: %d', ...
                app.CorrectParams.SupportPoints); ...
                sprintf('Median window: %d', ...
                app.CorrectParams.MedianWindow); ...
                ''; ...
                'The previous trial preview was cleared.'; ...
                'Press Trial correction to calculate a new preview.'});
        end

        % Button pushed function: TrialcorrectionButton
        function TrialcorrectionButtonPushed(app, event)
            progressDialog = [];
            
            try
                
                % =============================================================
                % 1. VALIDATION
                % =============================================================
                
                if isempty(app.CubeOriginal)
                    
                    uialert( ...
                        app.UIFigure, ...
                        'Load a Raman scan first.', ...
                        'No Raman scan');
                    
                    return
                end
                
                if isempty(app.CandidateTable) || ...
                        height(app.CandidateTable) == 0
                    
                    uialert( ...
                        app.UIFigure, ...
                        'Run cosmic detection first.', ...
                        'No cosmic candidates');
                    
                    return
                end
                
                app.CandidateTable = ...
                    app.ensureCosmicCandidateTableSchema( ...
                    app.CandidateTable);
                
                acceptedIndices = ...
                    find(app.CandidateTable.Accepted);
                
                if isempty(acceptedIndices)
                    
                    uialert( ...
                        app.UIFigure, ...
                        'Accept at least one cosmic candidate first.', ...
                        'No accepted cosmic');
                    
                    return
                end
                
                % =============================================================
                % 2. CURRENT CORRECTION PARAMETERS
                % =============================================================
                
                methodName = ...
                    char(string(app.MethodsDropDown_2.Value));
                
                severityName = ...
                    char(string(app.ModesDropDown_2.Value));
                
                if isempty(methodName)
                    
                    methodName = ...
                        'PCHIP interpolation';
                    
                    app.MethodsDropDown_2.Value = ...
                        methodName;
                end
                
                if isempty(severityName)
                    
                    severityName = ...
                        'Medium';
                    
                    app.ModesDropDown_2.Value = ...
                        severityName;
                end
                
                app.CorrectParams = ...
                    app.getCosmicCorrectionPresetParams( ...
                    severityName, ...
                    methodName);
                
                app.CorrectParamsChecked = ...
                    true;
                
                % =============================================================
                % 3. TRIAL STARTS FROM COMMITTED DATA
                %
                % Never start from an earlier TrialCorrectedCube, otherwise
                % repeatedly pressing Trial would apply correction repeatedly.
                % =============================================================
                
                baseCube = ...
                    app.getCommittedCube();
                
                trialCube = ...
                    double(baseCube);
                
                progressDialog = ...
                    uiprogressdlg( ...
                    app.UIFigure, ...
                    'Title', ...
                    'Trial correction for all', ...
                    'Message', ...
                    sprintf( ...
                    'Trial correcting %d accepted events...', ...
                    numel(acceptedIndices)), ...
                    'Indeterminate', ...
                    'off', ...
                    'Cancelable', ...
                    'on', ...
                    'Value', ...
                    0);
                
                correctedEventCount = ...
                    0;
                
                % =============================================================
                % 4. APPLY TEMPORARY TRIAL TO EVERY ACCEPTED EVENT
                % =============================================================
                
                for n = 1:numel(acceptedIndices)
                    
                    if progressDialog.CancelRequested
                        
                        close(progressDialog);
                        progressDialog = [];
                        
                        app.TrialCorrectedCube = ...
                            [];
                        
                        app.CorrectionTrialMode = ...
                            false;
                        
                        uialert( ...
                            app.UIFigure, ...
                            'Trial correction was cancelled.', ...
                            'Trial cancelled');
                        
                        return
                    end
                    
                    i = ...
                        acceptedIndices(n);
                    
                    y = ...
                        round(double(app.CandidateTable.Y(i)));
                    
                    x = ...
                        round(double(app.CandidateTable.X(i)));
                    
                    if y < 1 || ...
                            y > size(trialCube, 1) || ...
                            x < 1 || ...
                            x > size(trialCube, 2)
                        
                        continue
                    end
                    
                    beforeSpectrum = ...
                        double(squeeze( ...
                        trialCube(y, x, :)));
                    
                    afterSpectrum = ...
                        app.correctSingleSpectrum( ...
                        beforeSpectrum, ...
                        app.CandidateTable.KStart(i), ...
                        app.CandidateTable.KEnd(i), ...
                        app.CorrectParams);
                    
                    trialCube(y, x, :) = ...
                        reshape( ...
                        afterSpectrum, ...
                        1, 1, []);
                    
                    correctedEventCount = ...
                        correctedEventCount + 1;
                    
                    progressDialog.Value = ...
                        n / numel(acceptedIndices);
                    
                    progressDialog.Message = ...
                        sprintf( ...
                        'Trial correcting event %d / %d', ...
                        n, ...
                        numel(acceptedIndices));
                    
                    drawnow limitrate;
                end
                
                if ~isempty(progressDialog) && ...
                        isvalid(progressDialog)
                    
                    close(progressDialog);
                end
                
                progressDialog = [];
                
                % =============================================================
                % 5. STORE THE COMPLETE TRIAL CUBE
                %
                % Candidate clicks now use getActiveCube(), which returns this
                % cube while CorrectionTrialMode is true.
                % =============================================================
                
                app.TrialCorrectedCube = ...
                    trialCube;
                
                app.CorrectionTrialMode = ...
                    true;
                
                app.LastTrialCandidateIndex = ...
                    NaN;
                
                app.LastTrialBeforeSpectrum = ...
                    [];
                
                app.LastTrialAfterSpectrum = ...
                    [];
                
                app.LastTrialQC = ...
                    struct( ...
                    'Mode', ...
                    'All accepted candidates', ...
                    'CandidateCount', ...
                    correctedEventCount, ...
                    'Method', ...
                    string(app.CorrectParams.Method), ...
                    'Severity', ...
                    string(app.CorrectParams.Severity), ...
                    'Committed', ...
                    false);
                
                % =============================================================
                % 6. DISPLAY ALL ACCEPTED SPECTRA BEFORE / AFTER TRIAL
                % =============================================================
                
                plotIndices = ...
                    acceptedIndices(:);
                
                sampled = ...
                    false;
                
                if numel(plotIndices) > app.MaxReviewPlotSpectra
                    
                    rng(1);
                    
                    plotIndices = ...
                        plotIndices( ...
                        randperm( ...
                        numel(plotIndices), ...
                        app.MaxReviewPlotSpectra));
                    
                    sampled = ...
                        true;
                end
                
                app.resetSpectrumAxis( ...
                    app.UIAxes);
                
                hold(app.UIAxes, 'on');
                
                beforeValues = ...
                    [];
                
                for q = 1:numel(plotIndices)
                    
                    i = ...
                        plotIndices(q);
                    
                    y = ...
                        round(double(app.CandidateTable.Y(i)));
                    
                    x = ...
                        round(double(app.CandidateTable.X(i)));
                    
                    spectrum = ...
                        double(squeeze( ...
                        baseCube(y, x, :)));
                    
                    plot( ...
                        app.UIAxes, ...
                        app.Wavenumber, ...
                        spectrum, ...
                        'Color', ...
                        [0.72 0.72 0.72], ...
                        'LineWidth', ...
                        0.30);
                    
                    beforeValues = ...
                        [beforeValues; spectrum(:)]; %#ok<AGROW>
                end
                
                hold(app.UIAxes, 'off');
                
                title( ...
                    app.UIAxes, ...
                    sprintf( ...
                    'Before trial | accepted spectra shown = %d', ...
                    numel(plotIndices)), ...
                    'Interpreter', ...
                    'none');
                
                app.setNiceSpectralLimits( ...
                    app.UIAxes, ...
                    app.Wavenumber, ...
                    beforeValues);
                
                app.addCosmicWNFocusOverlay( ...
                    app.UIAxes);
                
                app.resetSpectrumAxis( ...
                    app.UIAxes2);
                
                hold(app.UIAxes2, 'on');
                
                afterValues = ...
                    [];
                
                for q = 1:numel(plotIndices)
                    
                    i = ...
                        plotIndices(q);
                    
                    y = ...
                        round(double(app.CandidateTable.Y(i)));
                    
                    x = ...
                        round(double(app.CandidateTable.X(i)));
                    
                    spectrum = ...
                        double(squeeze( ...
                        trialCube(y, x, :)));
                    
                    plot( ...
                        app.UIAxes2, ...
                        app.Wavenumber, ...
                        spectrum, ...
                        'Color', ...
                        [0.20 0.30 0.88], ...
                        'LineWidth', ...
                        0.30);
                    
                    afterValues = ...
                        [afterValues; spectrum(:)]; %#ok<AGROW>
                end
                
                hold(app.UIAxes2, 'off');
                
                title( ...
                    app.UIAxes2, ...
                    sprintf( ...
                    'After trial | accepted spectra shown = %d', ...
                    numel(plotIndices)), ...
                    'Interpreter', ...
                    'none');
                
                app.setNiceSpectralLimits( ...
                    app.UIAxes2, ...
                    app.Wavenumber, ...
                    afterValues);
                
                app.addCosmicWNFocusOverlay( ...
                    app.UIAxes2);
                
                if sampled
                    
                    displayText = ...
                        sprintf( ...
                        'Displayed reproducible sample: %d / %d spectra.', ...
                        numel(plotIndices), ...
                        numel(acceptedIndices));
                    
                else
                    
                    displayText = ...
                        'All accepted spectra are displayed.';
                end
                
                app.setText({ ...
                    'TRIAL CORRECTION — ALL ACCEPTED EVENTS'; ...
                    '================================'; ...
                    ''; ...
                    sprintf('Accepted events trial-corrected: %d', ...
                    correctedEventCount); ...
                    ['Method: ' char(app.CorrectParams.Method)]; ...
                    ['Severity: ' char(app.CorrectParams.Severity)]; ...
                    sprintf('ExpandPoints: %d', ...
                    app.CorrectParams.ExpandPoints); ...
                    sprintf('SupportPoints: %d', ...
                    app.CorrectParams.SupportPoints); ...
                    sprintf('MedianWindow: %d', ...
                    app.CorrectParams.MedianWindow); ...
                    ''; ...
                    displayText; ...
                    ''; ...
                    'Trial preview is active.'; ...
                    'Selecting any candidate in the Tree now displays'; ...
                    'its trial-corrected spectrum.'; ...
                    ''; ...
                    'TrialCorrectedCube is temporary only.'; ...
                    'Artifact and saved data will not use it.'; ...
                    'Press Apply for all to commit the result.'});
                
            catch ME
                
                if ~isempty(progressDialog)
                    
                    try
                        if isvalid(progressDialog)
                            close(progressDialog);
                        end
                    catch
                    end
                end
                
                app.TrialCorrectedCube = ...
                    [];
                
                app.CorrectionTrialMode = ...
                    false;
                
                disp(getReport( ...
                    ME, ...
                    'extended', ...
                    'hyperlinks', ...
                    'off'));
                
                if isempty(ME.stack)
                    
                    locationText = ...
                        'Location unavailable';
                    
                else
                    
                    locationText = ...
                        sprintf( ...
                        '%s, line %d', ...
                        ME.stack(1).name, ...
                        ME.stack(1).line);
                end
                
                uialert( ...
                    app.UIFigure, ...
                    sprintf( ...
                    '%s\n\nLocation: %s', ...
                    ME.message, ...
                    locationText), ...
                    'Trial correction error');
            end
        end

        % Button pushed function: ApplyforallButton
        function ApplyforallButtonPushed(app, event)
            progressDialog = [];
            
            try
                
                % =============================================================
                % 1. VALIDATION
                % =============================================================
                
                if isempty(app.CubeOriginal)
                    
                    uialert( ...
                        app.UIFigure, ...
                        'Load a Raman scan first.', ...
                        'No Raman scan');
                    
                    return
                end
                
                if ~app.CosmicReviewFinalized
                    
                    uialert( ...
                        app.UIFigure, ...
                        'Confirm the final cosmic list first.', ...
                        'Finalize required');
                    
                    return
                end
                
                if isempty(app.CandidateTable)
                    
                    app.CandidateTable = ...
                        app.makeEmptyCosmicCandidateTable();
                    
                else
                    
                    app.CandidateTable = ...
                        app.ensureCosmicCandidateTableSchema( ...
                        app.CandidateTable);
                end
                
                acceptedIndices = ...
                    find(app.CandidateTable.Accepted);
                
                % =============================================================
                % 2. CURRENT CORRECTION PARAMETERS
                % =============================================================
                
                methodName = ...
                    char(string(app.MethodsDropDown_2.Value));
                
                severityName = ...
                    char(string(app.ModesDropDown_2.Value));
                
                if isempty(methodName)
                    
                    methodName = ...
                        'PCHIP interpolation';
                    
                    app.MethodsDropDown_2.Value = ...
                        methodName;
                end
                
                if isempty(severityName)
                    
                    severityName = ...
                        'Medium';
                    
                    app.ModesDropDown_2.Value = ...
                        severityName;
                end
                
                app.CorrectParams = ...
                    app.getCosmicCorrectionPresetParams( ...
                    severityName, ...
                    methodName);
                
                app.CorrectParamsChecked = ...
                    true;
                
                % =============================================================
                % 3. CONFIRM EMPTY ACCEPTED LIST
                % =============================================================
                
                if isempty(acceptedIndices)
                    
                    choice = ...
                        uiconfirm( ...
                        app.UIFigure, ...
                        { ...
                        'There are no accepted cosmic events.'; ...
                        ''; ...
                        'The auxiliary 2000–2750 cm^{-1} detector can still'; ...
                        'clean the part excluded from the selected WN focus.'; ...
                        ''; ...
                        'Continue?'}, ...
                        'No accepted events', ...
                        'Options', { ...
                        'Continue', ...
                        'Cancel'}, ...
                        'DefaultOption', ...
                        'Continue', ...
                        'CancelOption', ...
                        'Cancel');
                    
                    if ~strcmp(choice, 'Continue')
                        return
                    end
                end
                
                % =============================================================
                % 4. ALWAYS RESTART FROM RAW ORIGINAL DATA
                %
                % CubeOriginal is never modified.
                % =============================================================
                
                correctedCube = ...
                    double(app.CubeOriginal);
                
                totalMainEvents = ...
                    numel(acceptedIndices);
                
                progressDialog = ...
                    uiprogressdlg( ...
                    app.UIFigure, ...
                    'Title', ...
                    'Cosmic correction', ...
                    'Message', ...
                    sprintf( ...
                    'Correcting %d accepted events...', ...
                    totalMainEvents), ...
                    'Indeterminate', ...
                    'off', ...
                    'Cancelable', ...
                    'on', ...
                    'Value', ...
                    0);
                
                % Reset only the committed correction flags.
                if ~isempty(app.CandidateTable)
                    
                    app.CandidateTable.Corrected(:) = ...
                        false;
                end
                
                % =============================================================
                % 5. APPLY USER-ACCEPTED CORRECTIONS
                % =============================================================
                
                for n = 1:totalMainEvents
                    
                    if progressDialog.CancelRequested
                        
                        close(progressDialog);
                        progressDialog = [];
                        
                        uialert( ...
                            app.UIFigure, ...
                            'Cosmic correction was cancelled.', ...
                            'Correction cancelled');
                        
                        return
                    end
                    
                    i = ...
                        acceptedIndices(n);
                    
                    y = ...
                        round(double(app.CandidateTable.Y(i)));
                    
                    x = ...
                        round(double(app.CandidateTable.X(i)));
                    
                    if y < 1 || ...
                            y > size(correctedCube, 1) || ...
                            x < 1 || ...
                            x > size(correctedCube, 2)
                        
                        continue
                    end
                    
                    spectrum = ...
                        double(squeeze( ...
                        correctedCube(y, x, :)));
                    
                    correctedSpectrum = ...
                        app.correctSingleSpectrum( ...
                        spectrum, ...
                        app.CandidateTable.KStart(i), ...
                        app.CandidateTable.KEnd(i), ...
                        app.CorrectParams);
                    
                    correctedCube(y, x, :) = ...
                        reshape( ...
                        correctedSpectrum, ...
                        1, 1, []);
                    
                    app.CandidateTable.Corrected(i) = ...
                        true;
                    
                    if totalMainEvents > 0
                        
                        progressDialog.Value = ...
                            0.75 * n / totalMainEvents;
                    end
                    
                    progressDialog.Message = ...
                        sprintf( ...
                        'Correcting accepted event %d / %d', ...
                        n, ...
                        totalMainEvents);
                    
                    drawnow limitrate;
                end
                
                % =============================================================
                % 6. AUTOMATIC AUXILIARY CORRECTION
                %
                % Only the missing part of 2000–2750 cm^{-1} is searched.
                % Detector: Medium derivative + residual.
                % Raw CubeOriginal remains untouched.
                % =============================================================
                
                progressDialog.Value = ...
                    0.78;
                
                progressDialog.Message = ...
                    'Checking excluded 2000–2750 cm^{-1} region...';
                
                drawnow;
                
                [correctedCube, auxiliaryInfo] = ...
                    app.autoCorrectAuxiliaryCosmicRegion( ...
                    correctedCube);
                
                progressDialog.Value = ...
                    1;
                
                progressDialog.Message = ...
                    'Finalizing corrected cube...';
                
                drawnow;
                
                if ~isempty(progressDialog) && ...
                        isvalid(progressDialog)
                    
                    close(progressDialog);
                end
                
                progressDialog = [];
                
                % =============================================================
                % 7. COMMIT RESULT
                % =============================================================
                
                app.CorrectedCube = ...
                    correctedCube;
                
                app.TrialCorrectedCube = ...
                    [];
                
                app.CorrectionTrialMode = ...
                    false;
                
                app.CorrectionReady = ...
                    true;
                
                app.LastTrialCandidateIndex = ...
                    NaN;
                
                app.LastTrialBeforeSpectrum = ...
                    [];
                
                app.LastTrialAfterSpectrum = ...
                    [];
                
                app.LastTrialQC = ...
                    struct();
                
                app.LastDetectionParamsAfterCorrection = ...
                    app.DetectionParams;
                
                app.LastDetectionParamSignature = ...
                    app.detectionParamSignature( ...
                    app.DetectionParams);
                
                app.CandidateSummary = ...
                    app.computeCandidateSummary();
                
                app.ConfirmedMask = ...
                    app.buildMaskFromAcceptedCandidates();
                
                app.populateCandidateTree(true);
                
                app.setCosmicWorkflowControls( ...
                    'corrected');
                
                app.plotConfirmedReview();
                
                % =============================================================
                % 8. STATUS
                % =============================================================
                
                if auxiliaryInfo.Enabled
                    
                    auxiliaryStatus = ...
                        { ...
                        sprintf('Auxiliary range: %.0f–%.0f cm^{-1}', ...
                        auxiliaryInfo.Range(1), ...
                        auxiliaryInfo.Range(2)); ...
                        sprintf('Auxiliary candidates detected: %d', ...
                        auxiliaryInfo.DetectedCount); ...
                        sprintf('Auxiliary candidates corrected: %d', ...
                        auxiliaryInfo.CorrectedCount)};
                    
                else
                    
                    auxiliaryStatus = ...
                        { ...
                        'Auxiliary 2000–2750 cm^{-1} correction: not required.'; ...
                        'The user WN focus already covered this region, or'; ...
                        'the range is unavailable in this scan.'};
                end
                
                app.setText( ...
                    [ ...
                    { ...
                    'COSMIC CORRECTION COMPLETE'; ...
                    '================================'; ...
                    ''; ...
                    sprintf('Accepted events corrected: %d', ...
                    nnz(app.CandidateTable.Corrected)); ...
                    ['Method: ' char(app.CorrectParams.Method)]; ...
                    ['Severity: ' char(app.CorrectParams.Severity)]; ...
                    ''; ...
                    'CubeOriginal remains unchanged.'; ...
                    'CorrectedCube contains the committed processing data.'; ...
                    ''}; ...
                    auxiliaryStatus; ...
                    { ...
                    ''; ...
                    'Trial preview was cleared.'; ...
                    'Artifact will receive CorrectedCube only.'; ...
                    ''; ...
                    'Press Review to inspect the corrected result.'} ...
                    ]);
                
            catch ME
                
                if ~isempty(progressDialog)
                    
                    try
                        if isvalid(progressDialog)
                            close(progressDialog);
                        end
                    catch
                    end
                end
                
                disp(getReport( ...
                    ME, ...
                    'extended', ...
                    'hyperlinks', ...
                    'off'));
                
                if isempty(ME.stack)
                    
                    locationText = ...
                        'Location unavailable';
                    
                else
                    
                    locationText = ...
                        sprintf( ...
                        '%s, line %d', ...
                        ME.stack(1).name, ...
                        ME.stack(1).line);
                end
                
                uialert( ...
                    app.UIFigure, ...
                    sprintf( ...
                    '%s\n\nLocation: %s', ...
                    ME.message, ...
                    locationText), ...
                    'Cosmic correction error');
            end
        end

        % Button pushed function: ReviewButton
        function ReviewButtonPushed(app, event)
            try
                
                if isempty(app.CubeOriginal)
                    
                    uialert( ...
                        app.UIFigure, ...
                        'No Raman scan is loaded.', ...
                        'No Raman scan');
                    
                    return
                end
                
                hasCandidates = ...
                    ~isempty(app.CandidateTable) && ...
                    height(app.CandidateTable) > 0;
                
                hasAccepted = ...
                    hasCandidates && ...
                    any(app.CandidateTable.Accepted);
                
                hasCorrected = ...
                    ~isempty(app.CorrectedCube);
                
                % =============================================================
                % FIRST REVIEW MENU
                % =============================================================
                
                mainChoice = ...
                    uiconfirm( ...
                    app.UIFigure, ...
                    { ...
                    'Choose the review scope:'; ...
                    ''; ...
                    'Candidate review:'; ...
                    '  Inspect detected/accepted cosmic events.'; ...
                    ''; ...
                    'Whole-scan review:'; ...
                    '  Inspect all usable spectra to verify cleanliness.'}, ...
                    'Cosmic review', ...
                    'Options', { ...
                    'Candidate review', ...
                    'Whole-scan review', ...
                    'Map review', ...
                    'Cancel'}, ...
                    'DefaultOption', ...
                    'Whole-scan review', ...
                    'CancelOption', ...
                    'Cancel');
                
                switch mainChoice
                    
                    % =========================================================
                    % CANDIDATE REVIEW SUBMENU
                    % =========================================================
                    
                    case 'Candidate review'
                        
                        if ~hasCandidates
                            
                            uialert( ...
                                app.UIFigure, ...
                                'No cosmic candidate is available.', ...
                                'No candidate');
                            
                            return
                        end
                        
                        if hasAccepted
                            
                            candidateChoice = ...
                                uiconfirm( ...
                                app.UIFigure, ...
                                { ...
                                'Choose candidate review:'; ...
                                ''; ...
                                'All candidates includes automatic and manual'; ...
                                'candidates from every detection run.'}, ...
                                'Candidate review', ...
                                'Options', { ...
                                'All candidate spectra', ...
                                'Accepted spectra', ...
                                'Candidate summary', ...
                                'Cancel'}, ...
                                'DefaultOption', ...
                                'All candidate spectra', ...
                                'CancelOption', ...
                                'Cancel');
                            
                        else
                            
                            candidateChoice = ...
                                uiconfirm( ...
                                app.UIFigure, ...
                                'Choose candidate review:', ...
                                'Candidate review', ...
                                'Options', { ...
                                'All candidate spectra', ...
                                'Candidate summary', ...
                                'Cancel'}, ...
                                'DefaultOption', ...
                                'All candidate spectra', ...
                                'CancelOption', ...
                                'Cancel');
                        end
                        
                        switch candidateChoice
                            
                            case 'All candidate spectra'
                                
                                app.plotAllDetectedCandidateSpectraReview();
                                
                            case 'Accepted spectra'
                                
                                if hasCorrected
                                    
                                    acceptedChoice = ...
                                        uiconfirm( ...
                                        app.UIFigure, ...
                                        'Choose accepted spectra view:', ...
                                        'Accepted spectra review', ...
                                        'Options', { ...
                                        'Raw accepted spectra', ...
                                        'Raw vs corrected', ...
                                        'Cancel'}, ...
                                        'DefaultOption', ...
                                        'Raw vs corrected', ...
                                        'CancelOption', ...
                                        'Cancel');
                                    
                                    if strcmp( ...
                                            acceptedChoice, ...
                                            'Raw accepted spectra')
                                        
                                        app.plotConfirmedRawSpectraReview();
                                        
                                    elseif strcmp( ...
                                            acceptedChoice, ...
                                            'Raw vs corrected')
                                        
                                        app.plotCorrectedSpectraReview();
                                    end
                                    
                                else
                                    
                                    app.plotConfirmedRawSpectraReview();
                                end
                                
                            case 'Candidate summary'
                                
                                app.plotCandidateSummaryView();
                                
                            otherwise
                                return
                        end
                        
                        % =========================================================
                        % WHOLE SCAN REVIEW
                        % =========================================================
                        
                    case 'Whole-scan review'
                        
                        app.plotAllUsableSpectraBeforeAfterReview();
                        
                        % =========================================================
                        % MAP REVIEW SUBMENU
                        % =========================================================
                        
                    case 'Map review'
                        
                        if hasCorrected
                            
                            mapChoice = ...
                                uiconfirm( ...
                                app.UIFigure, ...
                                'Choose map review:', ...
                                'Map review', ...
                                'Options', { ...
                                'Raw Raman map', ...
                                'Raw vs corrected maps', ...
                                'Candidate count map', ...
                                'Cancel'}, ...
                                'DefaultOption', ...
                                'Raw vs corrected maps', ...
                                'CancelOption', ...
                                'Cancel');
                            
                        else
                            
                            mapChoice = ...
                                uiconfirm( ...
                                app.UIFigure, ...
                                'Choose map review:', ...
                                'Map review', ...
                                'Options', { ...
                                'Raw Raman map', ...
                                'Candidate count map', ...
                                'Cancel'}, ...
                                'DefaultOption', ...
                                'Raw Raman map', ...
                                'CancelOption', ...
                                'Cancel');
                        end
                        
                        switch mapChoice
                            
                            case 'Raw Raman map'
                                
                                app.plotRawMap();
                                
                            case 'Raw vs corrected maps'
                                
                                app.plotCosmicBeforeAfterMapReview();
                                
                            case 'Candidate count map'
                                
                                if hasCandidates
                                    app.plotCandidateSummaryView();
                                else
                                    app.plotRawMap();
                                end
                                
                            otherwise
                                return
                        end
                        
                    otherwise
                        return
                end
                
            catch ME
                
                disp(getReport( ...
                    ME, ...
                    'extended', ...
                    'hyperlinks', ...
                    'off'));
                
                if isempty(ME.stack)
                    
                    locationText = ...
                        'Location unavailable';
                    
                else
                    
                    locationText = ...
                        sprintf( ...
                        '%s, line %d', ...
                        ME.stack(1).name, ...
                        ME.stack(1).line);
                end
                
                uialert( ...
                    app.UIFigure, ...
                    sprintf( ...
                    '%s\n\nLocation: %s', ...
                    ME.message, ...
                    locationText), ...
                    'Cosmic review error');
            end
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            try
                
                % Ignore only programmatic Tree reconstruction.
                if app.IsUpdatingTree
                    return
                end
                
                if isempty(app.CandidateTable) || ...
                        height(app.CandidateTable) == 0
                    return
                end
                
                % Prefer nodes supplied directly by the event.
                selectedNodes = [];
                
                try
                    selectedNodes = ...
                        event.SelectedNodes;
                catch
                    selectedNodes = ...
                        app.Tree.SelectedNodes;
                end
                
                if isempty(selectedNodes)
                    return
                end
                
                % =============================================================
                % FIND A SELECTED CANDIDATE NODE
                %
                % For a normal click there is one selected node.
                % For Ctrl/Shift selection, display the candidate that differs
                % from the candidate currently shown.
                % =============================================================
                
                candidateIndices = [];
                
                for n = 1:numel(selectedNodes)
                    
                    nodeData = ...
                        selectedNodes(n).NodeData;
                    
                    if isempty(nodeData) || ...
                            ~isstruct(nodeData) || ...
                            ~isfield(nodeData, 'Type')
                        continue
                    end
                    
                    nodeType = ...
                        char(string(nodeData.Type));
                    
                    if strcmp(nodeType, 'candidate') && ...
                            isfield(nodeData, 'Index')
                        
                        idxValue = ...
                            round(double(nodeData.Index));
                        
                        if isfinite(idxValue) && ...
                                idxValue >= 1 && ...
                                idxValue <= height(app.CandidateTable)
                            
                            candidateIndices(end + 1, 1) = ...
                                idxValue; %#ok<AGROW>
                        end
                    end
                end
                
                candidateIndices = ...
                    unique(candidateIndices, 'stable');
                
                if ~isempty(candidateIndices)
                    
                    if numel(candidateIndices) == 1
                        
                        idx = ...
                            candidateIndices(1);
                        
                    else
                        
                        % In multiselect mode, prefer a candidate different from
                        % the one currently displayed.
                        newIndices = ...
                            candidateIndices( ...
                            candidateIndices ~= ...
                            app.CurrentCandidateIndex);
                        
                        if isempty(newIndices)
                            
                            idx = ...
                                candidateIndices(end);
                            
                        else
                            
                            idx = ...
                                newIndices(end);
                        end
                    end
                    
                    % Exactly the same display route used by navigation.
                    app.CurrentCandidateIndex = ...
                        idx;
                    
                    app.displayCandidate(idx);
                    
                    drawnow;
                    
                    return
                end
                
                % =============================================================
                % PAGE NODE
                % =============================================================
                
                for n = 1:numel(selectedNodes)
                    
                    nodeData = ...
                        selectedNodes(n).NodeData;
                    
                    if isempty(nodeData) || ...
                            ~isstruct(nodeData) || ...
                            ~isfield(nodeData, 'Type')
                        continue
                    end
                    
                    nodeType = ...
                        char(string(nodeData.Type));
                    
                    if strcmp(nodeType, 'page') && ...
                            isfield(nodeData, 'Page')
                        
                        selectedPage = ...
                            round(double(nodeData.Page));
                        
                        selectedPage = ...
                            max( ...
                            1, ...
                            min( ...
                            app.CandidateNumPages, ...
                            selectedPage));
                        
                        app.CurrentCandidatePage = ...
                            selectedPage;
                        
                        app.populateCandidateTree(false);
                        
                        pageIndices = ...
                            app.getAutomaticCandidatePageIndices( ...
                            selectedPage);
                        
                        if ~isempty(pageIndices)
                            
                            idx = ...
                                pageIndices(1);
                            
                            app.CurrentCandidateIndex = ...
                                idx;
                            
                            app.selectCandidateNode(idx);
                            app.displayCandidate(idx);
                        end
                        
                        drawnow;
                        return
                    end
                end
                
                % =============================================================
                % SUMMARY OR ROOT NODE
                % =============================================================
                
                for n = 1:numel(selectedNodes)
                    
                    nodeData = ...
                        selectedNodes(n).NodeData;
                    
                    if isempty(nodeData) || ...
                            ~isstruct(nodeData) || ...
                            ~isfield(nodeData, 'Type')
                        continue
                    end
                    
                    nodeType = ...
                        char(string(nodeData.Type));
                    
                    if any(strcmp( ...
                            nodeType, ...
                            {'summary', ...
                            'pages', ...
                            'current_page', ...
                            'manual_group', ...
                            'scan'}))
                        
                        app.plotCandidateSummaryView();
                        return
                    end
                end
                
            catch ME
                
                disp(getReport( ...
                    ME, ...
                    'extended', ...
                    'hyperlinks', ...
                    'off'));
                
                if isempty(ME.stack)
                    
                    locationText = ...
                        'Unknown location';
                    
                else
                    
                    locationText = ...
                        sprintf( ...
                        '%s, line %d', ...
                        ME.stack(1).name, ...
                        ME.stack(1).line);
                end
                
                uialert( ...
                    app.UIFigure, ...
                    sprintf( ...
                    '%s\n\nLocation: %s', ...
                    ME.message, ...
                    locationText), ...
                    'Tree selection error');
            end
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
            
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            try
                % =====================================================
                % 1. VALIDATE LOADED SCAN
                % =====================================================
                
                if isempty(app.CubeOriginal)
                    
                    uialert( ...
                        app.UIFigure, ...
                        ['No Raman scan is currently loaded.' newline ...
                        'Press Load data before finishing Cosmic removal.'], ...
                        'No active scan');
                    
                    return
                end
                
                activeCube = app.getActiveCube();
                
                if isempty(activeCube) || ndims(activeCube) ~= 3
                    
                    uialert( ...
                        app.UIFigure, ...
                        'The active Raman cube is invalid.', ...
                        'Invalid active cube');
                    
                    return
                end
                
                [ny, nx, nWn] = size(activeCube);
                
                if isempty(app.Wavenumber) || ...
                        numel(app.Wavenumber) ~= nWn
                    
                    uialert( ...
                        app.UIFigure, ...
                        ['The wavenumber axis does not match the active ' ...
                        'Raman cube.'], ...
                        'Invalid wavenumber');
                    
                    return
                end
                
                % =====================================================
                % 2. CHECK COSMIC WORKFLOW STATE
                % =====================================================
                
                if isempty(app.CandidateTable)
                    
                    choice = uiconfirm( ...
                        app.UIFigure, ...
                        { ...
                        'No cosmic candidates are currently available.'; ...
                        ''; ...
                        'You may continue if cosmic detection found no'; ...
                        'candidate, or return and run detection first.'}, ...
                        'No cosmic candidates', ...
                        'Options', { ...
                        'Continue without correction', ...
                        'Stay here'}, ...
                        'DefaultOption', 'Stay here', ...
                        'CancelOption', 'Stay here');
                    
                    if ~strcmp(choice, 'Continue without correction')
                        return
                    end
                    
                else
                    nTotal = height(app.CandidateTable);
                    nAccepted = nnz(app.CandidateTable.Accepted);
                    nPending = nnz(~app.CandidateTable.Reviewed);
                    
                    % Pending candidates are allowed only after explicit
                    % confirmation, because they will not be corrected.
                    if nPending > 0
                        
                        choice = uiconfirm( ...
                            app.UIFigure, ...
                            { ...
                            sprintf('%d cosmic candidates remain pending.', ...
                            nPending); ...
                            ''; ...
                            'Pending candidates will not be corrected.'; ...
                            'Continue anyway?'}, ...
                            'Pending cosmic candidates', ...
                            'Options', { ...
                            'Continue', ...
                            'Stay here'}, ...
                            'DefaultOption', 'Stay here', ...
                            'CancelOption', 'Stay here');
                        
                        if ~strcmp(choice, 'Continue')
                            return
                        end
                    end
                    
                    % Accepted candidates should normally be corrected
                    % before finishing.
                    if nAccepted > 0 && isempty(app.CorrectedCube)
                        
                        choice = uiconfirm( ...
                            app.UIFigure, ...
                            { ...
                            sprintf('%d candidates are accepted.', ...
                            nAccepted); ...
                            ''; ...
                            'However, Apply for all has not produced a'; ...
                            'corrected cube yet.'; ...
                            ''; ...
                            'Return and press Apply for all.'}, ...
                            'Cosmic correction required', ...
                            'Options', {'Stay here'}, ...
                            'DefaultOption', 'Stay here', ...
                            'CancelOption', 'Stay here');
                        
                        return
                    end
                    
                    if nAccepted > 0 && ...
                            ismember( ...
                            'Corrected', ...
                            app.CandidateTable.Properties.VariableNames)
                        
                        acceptedNotCorrected = ...
                            app.CandidateTable.Accepted & ...
                            ~app.CandidateTable.Corrected;
                        
                        if any(acceptedNotCorrected)
                            
                            uialert( ...
                                app.UIFigure, ...
                                { ...
                                sprintf( ...
                                '%d accepted candidates are not corrected.', ...
                                nnz(acceptedNotCorrected)); ...
                                ''; ...
                                'Press Apply for all before Finish.'}, ...
                                'Correction incomplete');
                            
                            return
                        end
                    end
                    
                    if nTotal > 0 && ~app.CosmicConfirmed
                        
                        choice = uiconfirm( ...
                            app.UIFigure, ...
                            { ...
                            'The cosmic candidate list has not been'; ...
                            'confirmed with Confirm cosmic list.'; ...
                            ''; ...
                            'Confirm the list before finishing.'}, ...
                            'Cosmic list not confirmed', ...
                            'Options', {'Stay here'}, ...
                            'DefaultOption', 'Stay here', ...
                            'CancelOption', 'Stay here');
                        
                        return
                    end
                end
                
                % =====================================================
                % 3. BUILD FINAL COSMIC CUBE
                % =====================================================
                
                % getActiveCube returns CorrectedCube when it exists;
                % otherwise CubeOriginal.
                finalCube = double(app.getActiveCube());
                
                if ~isequal(size(finalCube), [ny nx nWn])
                    
                    uialert( ...
                        app.UIFigure, ...
                        'Final cosmic cube dimensions are inconsistent.', ...
                        'Invalid cosmic result');
                    
                    return
                end
                
                finiteMask = all(isfinite(finalCube), 3);
                
                if ~any(finiteMask(:))
                    
                    uialert( ...
                        app.UIFigure, ...
                        'The final cosmic cube contains no valid spectra.', ...
                        'Invalid cosmic result');
                    
                    return
                end
                
                % =====================================================
                % 4. FINAL USER CONFIRMATION
                % =====================================================
                
                if isempty(app.CandidateTable)
                    
                    nTotal = 0;
                    nAccepted = 0;
                    nCorrected = 0;
                    nIgnored = 0;
                    nPending = 0;
                    
                else
                    nTotal = height(app.CandidateTable);
                    nAccepted = nnz(app.CandidateTable.Accepted);
                    nIgnored = nnz( ...
                        app.CandidateTable.Reviewed & ...
                        ~app.CandidateTable.Accepted);
                    nPending = nnz(~app.CandidateTable.Reviewed);
                    
                    if ismember( ...
                            'Corrected', ...
                            app.CandidateTable.Properties.VariableNames)
                        
                        nCorrected = ...
                            nnz(app.CandidateTable.Corrected);
                    else
                        nCorrected = 0;
                    end
                end
                
                choice = uiconfirm( ...
                    app.UIFigure, ...
                    { ...
                    'Finish Cosmic removal with the current result?'; ...
                    ''; ...
                    sprintf('Scan: %s', app.CurrentScanName); ...
                    sprintf('Detected candidates: %d', nTotal); ...
                    sprintf('Accepted: %d', nAccepted); ...
                    sprintf('Corrected: %d', nCorrected); ...
                    sprintf('Ignored: %d', nIgnored); ...
                    sprintf('Pending: %d', nPending); ...
                    ''; ...
                    'The Cosmic result will be saved.'; ...
                    'You will then choose the next destination.'}, ...
                    'Finish Cosmic removal', ...
                    'Options', {'Finish and continue', 'Stay here'}, ...
                    'DefaultOption', 'Finish and continue', ...
                    'CancelOption', 'Stay here');
                
                if ~strcmp(choice, 'Finish and continue')
                    return
                end
                
                % =====================================================
                % 5. INITIALIZE OUTPUT FOLDERS
                % =====================================================
                
                app.initializeProjectPaths();
                
                % =====================================================
                % 6. SAVE FINAL COSMIC DATA
                % =====================================================
                
                savedMatPath = ...
                    app.saveFinalCosmicData(finalCube);
                
                % =====================================================
                % 7. SAVE QC EVIDENCE
                % =====================================================
                
                % =====================================================
                % 7. OPTIONAL COSMIC QC EVIDENCE
                %
                % Evidence generation is expensive because it exports
                % multiple 300-dpi figures. Do not run it automatically.
                % =====================================================
                
                evidenceFolder = '';
                
                hasAcceptedCandidates = ...
                    ~isempty(app.CandidateTable) && ...
                    any(app.CandidateTable.Accepted);
                
                if hasAcceptedCandidates
                    
                    evidenceChoice = ...
                        uiconfirm( ...
                        app.UIFigure, ...
                        { ...
                        'Generate the Cosmic QC evidence package?'; ...
                        ''; ...
                        'This may take some time because it creates'; ...
                        'multiple figures and reports.'; ...
                        ''; ...
                        'The saved Cosmic cube and state do not depend'; ...
                        'on this evidence package.'}, ...
                        'Optional Cosmic evidence', ...
                        'Options', { ...
                        'Skip evidence', ...
                        'Generate evidence'}, ...
                        'DefaultOption', ...
                        'Skip evidence', ...
                        'CancelOption', ...
                        'Skip evidence');
                    
                    if strcmp(evidenceChoice, 'Generate evidence')
                        
                        app.setText({ ...
                            'GENERATING COSMIC QC EVIDENCE'; ...
                            '================================'; ...
                            ''; ...
                            ['Scan: ' app.CurrentScanName]; ...
                            ''; ...
                            'Creating figures and reports...'});
                        
                        drawnow;
                        
                        evidenceFolder = ...
                            app.saveCosmicEvidencePackage(finalCube);
                    end
                end
                
                % =====================================================
                % 8. SAVE LIGHTWEIGHT STATE
                % =====================================================
                
                statePath = ...
                    app.saveCosmicStateData( ...
                    savedMatPath, ...
                    evidenceFolder);
                
                % =====================================================
                % 9. UPDATE MASTER LOG
                % =====================================================
                
                app.appendMasterLog( ...
                    'Cosmic', ...
                    savedMatPath, ...
                    statePath, ...
                    evidenceFolder);
                
                app.LastCosmicStatePath = statePath;
                app.LastCosmicEvidenceFolder = evidenceFolder;
                app.CosmicConfirmed = true;
                
                % =====================================================
                % 10. BUILD LIGHTWEIGHT PAYLOAD FOR SCANSPECTRA
                %
                % Do not copy the full cube into the parent app.
                % =====================================================
                
                payload = ...
                    app.buildHomePretreatPayload( ...
                    'Cosmic', ...
                    savedMatPath, ...
                    statePath, ...
                    evidenceFolder);
                
                payload.SourceApp = 'scanpretreat';
                payload.Stage = 'Cosmic';
                payload.ScanName = app.CurrentScanName;
                payload.ScanID = app.getCurrentScanID();
                payload.SavedMatPath = savedMatPath;
                payload.StatePath = statePath;
                payload.EvidenceFolder = evidenceFolder;
                payload.PayloadMode = 'lightweight_path_link';
                payload.HeavyDataIncluded = false;
                
                app.LastSavedPayload = ...
                    payload;
                
                % Do not notify the parent app yet.
                % Cosmic -> Artifact remains entirely inside scanpretreat.
                
                % =====================================================
                % =====================================================
                % 11. INVALIDATE DOWNSTREAM RESULTS
                %
                % A newly finalized Cosmic result makes every previous
                % Artifact and Background result scientifically stale.
                % Do this once, but do not change the selected tab yet.
                % =====================================================
                
                app.invalidateArtifactAndBackground();
                
                % =====================================================
                % 12. RECORD COMPLETION
                % =====================================================
                
                app.setText({ ...
                    'COSMIC REMOVAL SAVED'; ...
                    '================================'; ...
                    ''; ...
                    ['Scan: ' app.CurrentScanName]; ...
                    ['Saved MAT: ' savedMatPath]; ...
                    ['Saved state: ' statePath]; ...
                    ''; ...
                    sprintf('Detected candidates: %d', nTotal); ...
                    sprintf('Accepted candidates: %d', nAccepted); ...
                    sprintf('Corrected candidates: %d', nCorrected); ...
                    sprintf('Ignored candidates: %d', nIgnored); ...
                    sprintf('Pending candidates: %d', nPending); ...
                    ''; ...
                    'Previous Artifact and Background results were'; ...
                    'invalidated because the Cosmic result changed.'});
                
                
                % =============================================================
                % SEND COMMITTED COSMIC-CORRECTED CUBE TO SCANSPECTRA
                % =============================================================
                
                sentToScanSpectra = ...
                    app.sendActiveLoadedScanToMainApp( ...
                    'Finished Cosmic removal');
                
                if sentToScanSpectra
                    
                    app.setText({ ...
                        'COSMIC RESULT SAVED AND TRANSFERRED'; ...
                        '================================'; ...
                        ''; ...
                        ['Scan: ' app.CurrentScanName]; ...
                        ''; ...
                        'The committed cosmic-corrected cube was sent to scanspectra.'; ...
                        'TrialCorrectedCube was not transferred.'; ...
                        ''; ...
                        'The scan can now be opened from:'; ...
                        'Single spectra extraction.'});
                    
                else
                    
                    warning( ...
                        'RamanCube:CosmicTransferFailed', ...
                        ['Cosmic result was saved, but the active cube could not ' ...
                        'be transferred to scanspectra.']);
                end
                % =====================================================
                % NEXT ACTION
                % =====================================================
                
                % =============================================================
                % NEXT ACTION
                % =============================================================
                
                action = ...
                    app.chooseFinishAction('cosmic');
                
                switch action
                    
                    case 'Continue to Artifact'
                        
                        app.invalidateArtifactAndBackground();
                        
                        app.TabGroup.SelectedTab = ...
                            app.ArtifactremovalTab;
                        
                        app.setArtifactText({ ...
                            'ARTIFACT REMOVAL READY'; ...
                            '================================'; ...
                            ''; ...
                            ['Scan: ' app.CurrentScanName]; ...
                            ''; ...
                            'Cosmic result was finalized and saved.'; ...
                            'The committed Cosmic cube is used directly.'; ...
                            ''; ...
                            'Preparing Artifact display...'});
                        
                        drawnow;
                        
                        app.plotArtifactInitialView();
                        
                        app.setArtifactText({ ...
                            'ARTIFACT REMOVAL READY'; ...
                            '================================'; ...
                            ''; ...
                            ['Scan: ' app.CurrentScanName]; ...
                            sprintf('Cube size: %d x %d x %d', ...
                            size(finalCube, 1), ...
                            size(finalCube, 2), ...
                            size(finalCube, 3)); ...
                            sprintf('Deleted spectra excluded: %d', ...
                            nnz(app.DeletedSpectrumMask2D)); ...
                            ''; ...
                            'The Cosmic-corrected cube is active.'; ...
                            ''; ...
                            'Select an Artifact detection mode and press'; ...
                            'Detect artifact.'});
                        
                        drawnow limitrate;
                        
                    case 'Return to scanspectra'
                        
                        app.returnPayloadToMainApp( ...
                            payload);
                        
                        sentActiveScan = ...
                            app.sendActiveLoadedScanToMainApp( ...
                            'Finished Cosmic removal');
                        
                        if ~sentActiveScan
                            
                            warning( ...
                                'RamanCube:CosmicActiveTransferFailed', ...
                                ['Cosmic result was saved, but the active cube ' ...
                                'could not be transferred to scanspectra.']);
                        end
                        
                        app.showScanspectraParent();
                        
                    case 'Return to Home'
                        
                        app.returnPayloadToMainApp( ...
                            payload);
                        
                        sentActiveScan = ...
                            app.sendActiveLoadedScanToMainApp( ...
                            'Finished Cosmic removal');
                        
                        if ~sentActiveScan
                            
                            warning( ...
                                'RamanCube:CosmicActiveTransferFailed', ...
                                ['Cosmic result was saved, but the active cube ' ...
                                'could not be transferred to scanspectra.']);
                        end
                        
                        app.returnToHomeApp();
                        
                    otherwise
                        
                        % Stay in Cosmic.
                        app.TabGroup.SelectedTab = ...
                            app.CosmicremovalTab;
                        
                        app.setText({ ...
                            'COSMIC RESULT SAVED'; ...
                            '================================'; ...
                            ''; ...
                            ['Scan: ' app.CurrentScanName]; ...
                            ''; ...
                            'The Cosmic result remains active.'; ...
                            'You may continue reviewing or move to Artifact later.'});
                        
                        drawnow limitrate;
                end
            end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            
        end

        % Button pushed function: SaveimagesButton
        function SaveimagesButtonPushed(app, event)
            try
                app.initializeProjectPaths();
                app.ImageSaveRoot = app.Paths.FiguresCosmicRoot;
                choice = uiconfirm(app.UIFigure,'Which axis do you want to save?',...
                    'Save image',...
                    'Options',{'Axis 1','Axis 2','Both','Cancel'},...
                    'DefaultOption','Axis 1',...
                    'CancelOption','Cancel');
                if strcmp(choice,'Cancel')
                    return
                end
                defaultName = 'scanpretreat_image';
                if ~isempty(app.CurrentScanName)
                    defaultName = app.makeSafeFileName(app.CurrentScanName);
                end
                answ = inputdlg({'Image file name, without extension:'},'Save image name',[1 70],{defaultName});
                if isempty(answ)
                    return
                end
                baseName = app.makeSafeFileName(answ{1});
                if isempty(baseName)
                    baseName = defaultName;
                end
                stamp = datestr(now,'yyyymmdd_HHMMSS');
                savedFiles = {};
                if strcmp(choice,'Axis 1') || strcmp(choice,'Both')
                    outFile1 = fullfile(app.ImageSaveRoot,[baseName '_axis1_' stamp '.png']);
                    app.exportAxisImage(app.UIAxes,outFile1);
                    savedFiles{end+1} = outFile1;
                end
                if strcmp(choice,'Axis 2') || strcmp(choice,'Both')
                    outFile2 = fullfile(app.ImageSaveRoot,[baseName '_axis2_' stamp '.png']);
                    app.exportAxisImage(app.UIAxes2,outFile2);
                    savedFiles{end+1} = outFile2;
                end
                app.setText([{'IMAGE SAVED'}; savedFiles(:)]);
                uialert(app.UIFigure,sprintf('Saved %d image(s) at 300 dpi.',numel(savedFiles)),'Save images completed');
            catch ME
                uialert(app.UIFigure,ME.message,'Save images error');
            end
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            try
                value = app.ModeDropDown.Value;
                
                app.ArtifactParams = app.getArtifactPresetParams(value);
                app.ArtifactModeName = value;
                
                app.setArtifactText(app.makeArtifactParameterText(app.ArtifactParams,value,false));
                
                if ~isempty(app.CubeOriginal)
                    app.plotArtifactInitialView();
                end
                
            catch ME
                uialert(app.UIFigure,ME.message,'Artifact mode error');
            end
        end

        % Button pushed function: DetectartifactButton
        function DetectartifactButtonPushed(app, event)
            progressDialog = [];
            
            try
                
                if isempty(app.CubeOriginal)
                    
                    uialert( ...
                        app.UIFigure, ...
                        'Please load a scan first.', ...
                        'No scan');
                    
                    return
                end
                
                % =============================================================
                % VALIDATE AND FETCH COMMITTED DATA
                %
                % This never returns TrialCorrectedCube.
                % =============================================================
                
                cube = ...
                    app.validateCosmicToArtifactTransfer();
                
                wn = ...
                    double(app.Wavenumber(:));
                
                modeValue = ...
                    app.ModeDropDown.Value;
                
                params = ...
                    app.getArtifactPresetParams( ...
                    modeValue);
                
                if startsWith(char(modeValue), '5')
                    
                    params = ...
                        app.editArtifactParametersManual( ...
                        params);
                    
                    if isempty(params)
                        return
                    end
                end
                
                app.ArtifactParams = ...
                    params;
                
                app.ArtifactModeName = ...
                    char(modeValue);
                
                if ~isempty(app.CorrectedCube)
                    
                    inputDescription = ...
                        'Committed CorrectedCube after cosmic correction';
                    
                else
                    
                    inputDescription = ...
                        'CubeOriginal — no committed cosmic correction';
                end
                
                app.setArtifactText({ ...
                    'RUNNING ARTIFACT DETECTION'; ...
                    '================================'; ...
                    ''; ...
                    ['Mode: ' char(modeValue)]; ...
                    ['Input cube: ' inputDescription]; ...
                    ''; ...
                    'TrialCorrectedCube is never used for Artifact.'; ...
                    'Please wait...'});
                
                drawnow;
                
                progressDialog = ...
                    uiprogressdlg( ...
                    app.UIFigure, ...
                    'Title', ...
                    'Artifact detection', ...
                    'Message', ...
                    'Detecting horizontal stripe artifacts...', ...
                    'Indeterminate', ...
                    'on');
                
                result = ...
                    app.runArtifactDetection( ...
                    cube, ...
                    wn, ...
                    params);
                
                if ~isempty(progressDialog) && ...
                        isvalid(progressDialog)
                    
                    close(progressDialog);
                end
                
                progressDialog = [];
                
                app.ArtifactDisplayMapRaw = ...
                    result.DisplayMap;
                
                app.ArtifactDisplayMapClean = ...
                    result.CleanDisplayMap;
                
                app.ArtifactDisplayLabel = ...
                    result.DisplayLabel;
                
                app.ArtifactDisplayInpaintCube = ...
                    result.CubeDisplayInpaint;
                
                app.ArtifactDisplayInpaintInfo = ...
                    result.DisplayInpaintInfo;
                
                app.ArtifactMask2D = ...
                    result.FinalMask;
                
                app.ValidPixelMask2D = ...
                    result.ValidPixelMask;
                
                app.ArtifactStripeScoreMap = ...
                    result.StripeScoreMap;
                
                app.ArtifactBandConsistencyMap = ...
                    result.BandConsistencyMap;
                
                app.ArtifactSpectralZMap = ...
                    result.SpectralZMap;
                
                app.ArtifactStrongSeedMask = ...
                    result.StrongSeedMask;
                
                app.ArtifactWeakSupportMask = ...
                    result.WeakSupportMask;
                
                app.ArtifactHysteresisMask = ...
                    result.HysteresisMask;
                
                app.ArtifactRowBandMask = ...
                    result.RowBandMask;
                
                app.ArtifactRowEvidence = ...
                    result.RowEvidence;
                
                app.ArtifactTable = ...
                    result.ArtifactTable;
                
                app.ArtifactBandMaps = ...
                    result.BandMaps;
                
                app.ArtifactBandLabels = ...
                    result.BandLabels;
                
                app.ArtifactZStack = ...
                    result.ZStack;
                
                app.ArtifactReady = ...
                    true;
                
                app.ArtifactConfirmed = ...
                    false;
                
                app.plotArtifactResultOnTab();
                
                resultText = ...
                    app.makeArtifactParameterText( ...
                    params, ...
                    modeValue, ...
                    true);
                
                resultText = ...
                    [ ...
                    resultText; ...
                    { ...
                    ''; ...
                    'INPUT TRANSFER CHECK'; ...
                    '================================'; ...
                    ['Input: ' inputDescription]; ...
                    sprintf('Cube size: %d x %d x %d', ...
                    size(cube, 1), ...
                    size(cube, 2), ...
                    size(cube, 3)); ...
                    sprintf('WN points: %d', ...
                    numel(wn)); ...
                    sprintf('Auxiliary cosmic events corrected: %d', ...
                    app.AuxCosmicCorrectedCount); ...
                    'Trial preview excluded from Artifact input.'} ...
                    ];
                
                app.setArtifactText( ...
                    resultText);
                
            catch ME
                
                if ~isempty(progressDialog)
                    
                    try
                        if isvalid(progressDialog)
                            close(progressDialog);
                        end
                    catch
                    end
                end
                
                disp(getReport( ...
                    ME, ...
                    'extended', ...
                    'hyperlinks', ...
                    'off'));
                
                uialert( ...
                    app.UIFigure, ...
                    ME.message, ...
                    'Detect artifact error');
            end
        end

        % Value changed function: TextArea_2
        function TextArea_2ValueChanged(app, event)
            value = app.TextArea_2.Value;
            
        end

        % Button pushed function: SaveimagesButton_2
        function SaveimagesButton_2Pushed(app, event)
            try
                app.initializeProjectPaths();
                app.ArtifactImageRoot = app.Paths.FiguresArtifactRoot;
                if app.ArtifactReady && ~isempty(app.ArtifactMask2D)
                    app.plotArtifactResultOnTab();
                    drawnow;
                end
                choice = uiconfirm(app.UIFigure,'Which artifact image do you want to save?', ...
                    'Save artifact images', ...
                    'Options',{'Axis 1','Axis 2','Both','Cancel'}, ...
                    'DefaultOption','Both', ...
                    'CancelOption','Cancel');
                
                if strcmp(choice,'Cancel')
                    return
                end
                
                defaultName = 'artifact_image';
                if ~isempty(app.CurrentScanName)
                    defaultName = [app.makeSafeFileName(app.CurrentScanName) '_artifact'];
                end
                
                answ = inputdlg({'Image file name, without extension:'},'Save artifact image name',[1 70],{defaultName});
                
                if isempty(answ)
                    return
                end
                
                baseName = app.makeSafeFileName(answ{1});
                
                if isempty(baseName)
                    baseName = defaultName;
                end
                
                stamp = datestr(now,'yyyymmdd_HHMMSS');
                savedFiles = {};
                
                if strcmp(choice,'Axis 1') || strcmp(choice,'Both')
                    outFile1 = fullfile(app.ArtifactImageRoot,[baseName '_raw_mask_axis1_' stamp '.png']);
                    app.exportAxisImage(app.UIAxes3,outFile1);
                    savedFiles{end+1} = outFile1;
                end
                
                if strcmp(choice,'Axis 2') || strcmp(choice,'Both')
                    outFile2 = fullfile(app.ArtifactImageRoot,[baseName '_clean_display_axis2_' stamp '.png']);
                    app.exportAxisImage(app.UIAxes4,outFile2);
                    savedFiles{end+1} = outFile2;
                end
                
                app.setArtifactText([{'ARTIFACT IMAGE SAVED'}; savedFiles(:)]);
                uialert(app.UIFigure,sprintf('Saved %d image(s) at 300 dpi.',numel(savedFiles)),'Save images completed');
                
            catch ME
                uialert(app.UIFigure,ME.message,'Save artifact images error');
            end
        end

        % Button pushed function: FinishButton_2
        function FinishButton_2Pushed(app, event)
            try
                % =====================================================
                % 1. VALIDATE ARTIFACT RESULT
                % =====================================================
                
                if isempty(app.CubeOriginal)
                    
                    uialert( ...
                        app.UIFigure, ...
                        'No active Raman scan is available.', ...
                        'Artifact Finish');
                    
                    return
                end


                if ~app.ArtifactReady || ...
                        isempty(app.ArtifactMask2D) || ...
                        isempty(app.ValidPixelMask2D)
                    
                    choice = ...
                        uiconfirm( ...
                        app.UIFigure, ...
                        { ...
                        'Artifact detection has not been performed.'; ...
                        ''; ...
                        'Do you want to skip Artifact removal?'; ...
                        ''; ...
                        'The active Raman cube will remain unchanged.'}, ...
                        'Artifact not performed', ...
                        'Options', { ...
                        'Skip Artifact and continue', ...
                        'Stay in Artifact'}, ...
                        'DefaultOption', ...
                        'Stay in Artifact', ...
                        'CancelOption', ...
                        'Stay in Artifact');
                    
                    if ~strcmp(choice, 'Skip Artifact and continue')
                        return
                    end
                    
                    cube = ...
                        app.getCommittedCube();
                    
                    [ny, nx, ~] = ...
                        size(cube);
                    
                    roiMask = ...
                        all(isfinite(cube), 3);
                    
                    if ~isempty(app.CurrentROIMask) && ...
                            isequal(size(app.CurrentROIMask), [ny nx])
                        
                        roiMask = ...
                            roiMask & logical(app.CurrentROIMask);
                    end
                    
                    deletedMask = ...
                        false(ny, nx);
                    
                    if ~isempty(app.DeletedSpectrumMask2D) && ...
                            isequal(size(app.DeletedSpectrumMask2D), [ny nx])
                        
                        deletedMask = ...
                            logical(app.DeletedSpectrumMask2D);
                    end
                    
                    app.ArtifactMask2D = ...
                        false(ny, nx);
                    
                    app.ValidPixelMask2D = ...
                        roiMask & ~deletedMask;
                    
                    app.ArtifactTable = ...
                        table();
                    
                    app.ArtifactReady = ...
                        true;
                    
                    app.ArtifactConfirmed = ...
                        true;
                    
                    artifactSkipped = ...
                        true;
                    
                else
                    
                    artifactSkipped = ...
                        false;
                end
                
                cube = app.getActiveCube();
                
                [ny, nx, nWn] = size(cube);
                
                if numel(app.Wavenumber) ~= nWn
                    
                    uialert( ...
                        app.UIFigure, ...
                        'Wavenumber does not match the active cube.', ...
                        'Artifact Finish');
                    
                    return
                end
                
                if ~isequal( ...
                        size(app.ValidPixelMask2D), ...
                        [ny nx])
                    
                    uialert( ...
                        app.UIFigure, ...
                        'ValidPixelMask2D has incorrect dimensions.', ...
                        'Artifact Finish');
                    
                    return
                end
                
                if ~any(app.ValidPixelMask2D(:))
                    
                    uialert( ...
                        app.UIFigure, ...
                        'No valid pixels remain after Artifact removal.', ...
                        'Artifact Finish');
                    
                    return
                end
                
                % =====================================================
                % 2. SAVE ARTIFACT RESULT
                % =====================================================
                
                app.initializeProjectPaths();
                
                app.ArtifactConfirmed = true;
                
                savedMatPath = ...
                    app.saveFinalArtifactData();
                
                artifactEvidenceFolder = ...
                    app.Paths.ArtifactEvidenceRoot;
                
                if ~exist(artifactEvidenceFolder, 'dir')
                    mkdir(artifactEvidenceFolder);
                end
                
                statePath = ...
                    app.saveArtifactStateData( ...
                    savedMatPath, ...
                    artifactEvidenceFolder);
                
                app.appendMasterLog( ...
                    'Artifact', ...
                    savedMatPath, ...
                    statePath, ...
                    artifactEvidenceFolder);
                
                % =====================================================
                % 3. UPDATE LIGHTWEIGHT SAVED-RESULT METADATA
                % =====================================================
                
                payload = ...
                    app.buildHomePretreatPayload( ...
                    'Artifact', ...
                    savedMatPath, ...
                    statePath, ...
                    artifactEvidenceFolder);
                
                payload.Stage = ...
                    'Artifact';
                
                payload.SourceApp = ...
                    'scanpretreat';
                
                payload.PayloadMode = ...
                    'lightweight_path_link';
                
                payload.HeavyDataIncluded = ...
                    false;
                
                app.LastSavedPayload = ...
                    payload;
                
                % Send saved-path metadata for audit/history.
                app.returnPayloadToMainApp( ...
                    payload);
                
                % =====================================================
                % 4. SEND HEAVY ACTIVE CUBE AND MASKS TO SCANSPECTRA
                %
                % Cube:
                %   committed cosmic-corrected cube
                %
                % Artifact:
                %   excluded through ArtifactMask2D and ValidPixelMask2D
                % =====================================================
                
                sentActiveScan = ...
                    app.sendActiveLoadedScanToMainApp( ...
                    'Finished Artifact removal');
                
                if ~sentActiveScan
                    
                    warning( ...
                        'RamanCube:ArtifactActiveTransferFailed', ...
                        ['Artifact result was saved, but the heavy in-memory cube ' ...
                        'could not be transferred to scanspectra.']);
                end
                
                % A newly finalized Artifact mask invalidates every Background
                % segmentation derived from the old Artifact result.
                app.invalidateBackgroundResult();
                
                % =====================================================
                % NEXT ACTION
                % =====================================================
                
                action = ...
                    app.chooseFinishAction('artifact');
                
                switch action
                    
                    case 'Redo Cosmic'
                        
                        app.invalidateArtifactAndBackground();
                        
                        app.TabGroup.SelectedTab = ...
                            app.CosmicremovalTab;
                        
                        app.setText({ ...
                            'RETURNED TO COSMIC REMOVAL'; ...
                            '================================'; ...
                            ''; ...
                            ['Scan: ' app.CurrentScanName]; ...
                            ''; ...
                            'The saved Artifact result remains on disk.'; ...
                            'Current Artifact and Background states were invalidated.'});
                        
                        drawnow;
                        
                    case 'Stay in Artifact'
                        
                        app.TabGroup.SelectedTab = ...
                            app.ArtifactremovalTab;
                        
                        app.setArtifactText({ ...
                            'ARTIFACT RESULT SAVED'; ...
                            '================================'; ...
                            ''; ...
                            ['Scan: ' app.CurrentScanName]; ...
                            ['Saved MAT: ' savedMatPath]; ...
                            ['Saved state: ' statePath]; ...
                            ''; ...
                            'The Artifact result remains active.'});
                        
                        drawnow limitrate;
                        
                    case 'Return to scanspectra'
                        
                        app.returnPayloadToMainApp( ...
                            payload);
                        
                        sentActiveScan = ...
                            app.sendActiveLoadedScanToMainApp( ...
                            'Finished Artifact removal');
                        
                        if ~sentActiveScan
                            
                            warning( ...
                                'RamanCube:ArtifactActiveTransferFailed', ...
                                ['Artifact result was saved, but the active cube ' ...
                                'could not be transferred to scanspectra.']);
                        end
                        
                        app.showScanspectraParent();
                        
                    case 'Return to Home'
                        
                        app.returnPayloadToMainApp( ...
                            payload);
                        
                        sentActiveScan = ...
                            app.sendActiveLoadedScanToMainApp( ...
                            'Finished Artifact removal');
                        
                        if ~sentActiveScan
                            
                            warning( ...
                                'RamanCube:ArtifactActiveTransferFailed', ...
                                ['Artifact result was saved, but the active cube ' ...
                                'could not be transferred to scanspectra.']);
                        end
                        
                        app.returnToHomeApp();
                        
                    case 'Close Pretreat'
                        
                        app.UIFigure.Visible = ...
                            'off';
                        
                    otherwise
                        
                        % User closed the action dialog.
                        app.TabGroup.SelectedTab = ...
                            app.ArtifactremovalTab;
                        
                        drawnow limitrate;
                end
            catch ME
                
                uialert( ...
                    app.UIFigure, ...
                    getReport( ...
                    ME, ...
                    'extended', ...
                    'hyperlinks', ...
                    'off'), ...
                    'Artifact Finish error');
            end
        end

        % Button down function: UIAxes3
        function UIAxes3ButtonDown(app, event)
            
        end

        % Button down function: UIAxes4
        function UIAxes4ButtonDown(app, event)
            
        end

        % Value changed function: ViewDropDown
        function ViewDropDownValueChanged(app, event)
            try
                app.ArtifactViewMode = app.ViewDropDown.Value;
                
                if app.ArtifactReady && ~isempty(app.ArtifactMask2D)
                    app.plotArtifactResultOnTab();
                else
                    app.plotArtifactInitialView();
                end
                
            catch ME
                uialert(app.UIFigure,ME.message,'Artifact view error');
            end
        end

        % Value changed function: ColorsDropDown
        function ColorsDropDownValueChanged(app, event)
            try
                app.ArtifactColorMap = app.ColorsDropDown.Value;
                
                if app.ArtifactReady && ~isempty(app.ArtifactMask2D)
                    app.plotArtifactResultOnTab();
                else
                    app.plotArtifactInitialView();
                end
                
            catch ME
                uialert(app.UIFigure,ME.message,'Artifact colormap error');
            end
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % next spectrum
            app.moveCosmicTreeSelection(1);
        end

        % Button pushed function: ManualdetectButton
        function ManualdetectButtonPushed(app, event)
            if isempty(app.CubeOriginal)
                
                uialert( ...
                    app.UIFigure, ...
                    'Load a Raman scan first.', ...
                    'No Raman scan');
                
                return
            end
            
            if app.ManualDetectionActive
                return
            end
            
            app.ManualDetectionActive = true;
            
            try
                [ny, nx, nWn] = ...
                    size(app.CubeOriginal);
                
                % =============================================================
                % REOPEN COSMIC REVIEW WHEN MANUAL DETECTION IS USED LATER
                % =============================================================
                
                if app.CosmicReviewFinalized || app.CosmicConfirmed
                    
                    app.CosmicReviewFinalized = false;
                    app.CosmicConfirmed = false;
                    app.CorrectionReady = false;
                    
                    % The old corrected cube does not contain newly added events.
                    app.CorrectedCube = [];
                    app.TrialCorrectedCube = [];
                    app.CorrectionTrialMode = false;
                    
                    if ~isempty(app.CandidateTable)
                        
                        app.CandidateTable = ...
                            app.ensureCosmicCandidateTableSchema( ...
                            app.CandidateTable);
                        
                        app.CandidateTable.Corrected(:) = false;
                    end
                end
                
                % =============================================================
                % CURRENT USABLE SPATIAL SCOPE
                % =============================================================
                
                usableMask = ...
                    app.getCurrentUsableSpectrumMask();
                
                if isempty(usableMask) || ...
                        ~isequal(size(usableMask), [ny nx])
                    
                    usableMask = ...
                        all(isfinite(app.CubeOriginal), 3);
                end
                
                usableMask = ...
                    logical(usableMask);
                
                searchablePixelIdx = ...
                    find(usableMask);
                
                if isempty(searchablePixelIdx)
                    
                    uialert( ...
                        app.UIFigure, ...
                        'No usable spectrum remains in the current spatial scope.', ...
                        'No searchable spectrum');
                    
                    app.ManualDetectionActive = false;
                    return
                end
                
                % =============================================================
                % ACTIVE WAVENUMBER MASK
                % =============================================================
                
                if ~isempty(app.CosmicWNMask) && ...
                        numel(app.CosmicWNMask) == nWn
                    
                    activeWNMask = ...
                        logical(app.CosmicWNMask(:));
                    
                else
                    
                    activeWNMask = ...
                        true(nWn, 1);
                end
                
                if ~any(activeWNMask)
                    
                    uialert( ...
                        app.UIFigure, ...
                        'The active cosmic wavenumber region is empty.', ...
                        'No active wavenumber region');
                    
                    app.ManualDetectionActive = false;
                    return
                end
                
                % =============================================================
                % DISPLAY SPECTRA
                % =============================================================
                
                cubeForDisplay = ...
                    app.getActiveCube();
                
                X = ...
                    reshape( ...
                    cubeForDisplay, ...
                    ny * nx, ...
                    nWn);
                
                displayPixelIdx = ...
                    searchablePixelIdx;
                
                % Display sampling is only for responsiveness.
                % All usable pixels remain part of the selected spatial scope.
                if numel(displayPixelIdx) > app.MaxReviewPlotSpectra
                    
                    rng(1);
                    
                    displayPixelIdx = ...
                        displayPixelIdx( ...
                        randperm( ...
                        numel(displayPixelIdx), ...
                        app.MaxReviewPlotSpectra));
                end
                
                ax = app.UIAxes2;
                
                app.resetSpectrumAxis(ax);
                
                hold(ax, 'on');
                
                for k = 1:numel(displayPixelIdx)
                    
                    plot( ...
                        ax, ...
                        app.Wavenumber, ...
                        X(displayPixelIdx(k), :), ...
                        'Color', [0.80 0.80 0.80], ...
                        'LineWidth', 0.25, ...
                        'HitTest', 'off');
                end
                
                medianSpectrum = ...
                    median( ...
                    X(displayPixelIdx, :), ...
                    1, ...
                    'omitnan');
                
                plot( ...
                    ax, ...
                    app.Wavenumber, ...
                    medianSpectrum, ...
                    'k-', ...
                    'LineWidth', 1.8, ...
                    'HitTest', 'off');
                
                hold(ax, 'off');
                
                title( ...
                    ax, ...
                    { ...
                    'Manual cosmic ROI selection'; ...
                    'Draw rectangle(s) around suspected cosmic regions'}, ...
                    'Interpreter', ...
                    'none');
                
                app.setNiceSpectralLimits( ...
                    ax, ...
                    app.Wavenumber, ...
                    X(displayPixelIdx, :));
                
                app.addCosmicWNFocusOverlay(ax);
                
                app.setText({ ...
                    'MANUAL COSMIC ROI SELECTION'; ...
                    '================================'; ...
                    ''; ...
                    sprintf('Usable spatial spectra: %d', ...
                    numel(searchablePixelIdx)); ...
                    sprintf('Spectra displayed: %d', ...
                    numel(displayPixelIdx)); ...
                    ['WN mode: ' app.CosmicWNMode]; ...
                    ''; ...
                    'Draw a rectangle around a suspected cosmic region.'; ...
                    'Use Add more for additional regions.'; ...
                    ''; ...
                    'Selections outside the active WN region are rejected.'; ...
                    'Detection runs later through Cosmic detection.'});
                
                drawnow;
                
                % =============================================================
                % DRAW MULTIPLE WAVENUMBER RECTANGLES
                % =============================================================
                
                selectedWNMask = ...
                    false(nWn, 1);
                
                selectedRanges = ...
                    zeros(0, 2);
                
                keepDrawing = ...
                    true;
                
                while keepDrawing
                    
                    roi = ...
                        drawrectangle( ...
                        ax, ...
                        'Color', [0.85 0.20 0.10], ...
                        'LineWidth', 1.5);
                    
                    if isempty(roi) || ~isvalid(roi)
                        break
                    end
                    
                    wait(roi);
                    
                    if ~isvalid(roi)
                        break
                    end
                    
                    position = ...
                        double(roi.Position);
                    
                    wnLow = ...
                        min(position(1), ...
                        position(1) + position(3));
                    
                    wnHigh = ...
                        max(position(1), ...
                        position(1) + position(3));
                    
                    requestedWNMask = ...
                        app.Wavenumber >= wnLow & ...
                        app.Wavenumber <= wnHigh;
                    
                    insideMask = ...
                        requestedWNMask(:) & ...
                        activeWNMask;
                    
                    outsideMask = ...
                        requestedWNMask(:) & ...
                        ~activeWNMask;
                    
                    if ~any(requestedWNMask)
                        
                        delete(roi);
                        
                        uialert( ...
                            app.UIFigure, ...
                            'The selected rectangle contains no spectral point.', ...
                            'Invalid manual region', ...
                            'Icon', 'warning');
                        
                        continue
                    end
                    
                    if any(outsideMask) && ~any(insideMask)
                        
                        delete(roi);
                        
                        uialert( ...
                            app.UIFigure, ...
                            { ...
                            sprintf([ ...
                            'The selected region %.2f-%.2f cm^{-1} lies ' ...
                            'outside the active cosmic region.'], ...
                            wnLow, wnHigh); ...
                            ''; ...
                            ['Current WN mode: ' app.CosmicWNMode]; ...
                            ''; ...
                            'This region was not added.'}, ...
                            'Inactive wavenumber region', ...
                            'Icon', 'warning');
                        
                        continue
                    end
                    
                    if any(outsideMask)
                        
                        choice = ...
                            uiconfirm( ...
                            app.UIFigure, ...
                            { ...
                            sprintf([ ...
                            'Part of %.2f-%.2f cm^{-1} lies outside the ' ...
                            'active cosmic region.'], ...
                            wnLow, wnHigh); ...
                            ''; ...
                            'Only the active portion will be retained.'}, ...
                            'Partially inactive region', ...
                            'Options', { ...
                            'Use active portion', ...
                            'Ignore rectangle'}, ...
                            'DefaultOption', ...
                            'Use active portion', ...
                            'CancelOption', ...
                            'Ignore rectangle');
                        
                        if ~strcmp(choice, 'Use active portion')
                            
                            delete(roi);
                            continue
                        end
                    end
                    
                    selectedWNMask = ...
                        selectedWNMask | insideMask;
                    
                    activeSelectedWN = ...
                        app.Wavenumber(insideMask);
                    
                    selectedRanges(end + 1, :) = ... %#ok<AGROW>
                        [min(activeSelectedWN), ...
                        max(activeSelectedWN)];
                    
                    roi.Color = ...
                        [0.15 0.65 0.20];
                    
                    choice = ...
                        uiconfirm( ...
                        app.UIFigure, ...
                        { ...
                        sprintf( ...
                        'Selected active region: %.2f-%.2f cm^{-1}', ...
                        min(activeSelectedWN), ...
                        max(activeSelectedWN)); ...
                        ''; ...
                        'Add another manual region?'}, ...
                        'Manual cosmic region', ...
                        'Options', { ...
                        'Add more', ...
                        'Finish', ...
                        'Cancel all'}, ...
                        'DefaultOption', ...
                        'Finish', ...
                        'CancelOption', ...
                        'Cancel all');
                    
                    switch choice
                        
                        case 'Add more'
                            
                            keepDrawing = true;
                            
                        case 'Finish'
                            
                            keepDrawing = false;
                            
                        otherwise
                            
                            selectedWNMask(:) = false;
                            selectedRanges = zeros(0, 2);
                            keepDrawing = false;
                    end
                end
                
                % =============================================================
                % STORE MANUAL SELECTION
                % =============================================================
                
                if ~any(selectedWNMask)
                    
                    app.ManualDetectionActive = false;
                    
                    app.setText({ ...
                        'MANUAL COSMIC ROI CANCELLED'; ...
                        '================================'; ...
                        ''; ...
                        'No active wavenumber region was stored.'});
                    
                    return
                end
                
                app.ManualCosmicSpatialMask = ...
                    usableMask;
                
                app.ManualCosmicWNMask = ...
                    selectedWNMask;
                
                app.ManualCosmicROIReady = ...
                    true;
                
                app.ManualCosmicROICount = ...
                    size(selectedRanges, 1);
                
                summaryLines = ...
                    cell(size(selectedRanges, 1), 1);
                
                for k = 1:size(selectedRanges, 1)
                    
                    summaryLines{k} = ...
                        sprintf( ...
                        'Region %d: %.2f-%.2f cm^{-1}', ...
                        k, ...
                        selectedRanges(k, 1), ...
                        selectedRanges(k, 2));
                end
                
                app.ManualCosmicROISummary = ...
                    summaryLines;
                
                app.setCosmicWorkflowControls('loaded');
                
                app.setText([ ...
                    { ...
                    'MANUAL COSMIC ROI STORED'; ...
                    '================================'; ...
                    ''; ...
                    sprintf('Spatial spectra included: %d', ...
                    nnz(app.ManualCosmicSpatialMask)); ...
                    sprintf('Manual WN regions: %d', ...
                    app.ManualCosmicROICount); ...
                    ''; ...
                    'Press Cosmic detection and choose:'; ...
                    'Re-detect selected ROIs'; ...
                    ''}; ...
                    summaryLines]);
                
                app.ManualDetectionActive = false;
                
                drawnow;
                
            catch ME
                
                app.ManualDetectionActive = false;
                
                uialert( ...
                    app.UIFigure, ...
                    getReport( ...
                    ME, ...
                    'extended', ...
                    'hyperlinks', ...
                    'off'), ...
                    'Manual cosmic ROI error', ...
                    'Icon', ...
                    'error');
            end
        end

        % Value changed function: ModesDropDown_2
        function ModesDropDown_2ValueChanged(app, event)
            selectedMode = ...
                char(app.ModesDropDown_2.Value);
            
            if isempty(selectedMode)
                return
            end
            
            app.CosmicCorrectionParameterMode = ...
                selectedMode;
            
            app.CorrectParams = ...
                app.getCosmicCorrectionPresetParams( ...
                selectedMode, ...
                app.MethodsDropDown_2.Value);
            
            app.CorrectParamsChecked = ...
                true;
            
            app.CorrectionTrialMode = ...
                false;
            
            app.LastTrialCandidateIndex = ...
                NaN;
            
            app.LastTrialBeforeSpectrum = ...
                [];
            
            app.LastTrialAfterSpectrum = ...
                [];
            
            app.LastTrialQC = ...
                struct();
            
            app.setText({ ...
                'CORRECTION SEVERITY UPDATED'; ...
                '================================'; ...
                ['Severity: ' selectedMode]; ...
                ['Method: ' ...
                app.MethodsDropDown_2.Value]; ...
                sprintf('Expand points: %d', ...
                app.CorrectParams.ExpandPoints); ...
                sprintf('Support points per side: %d', ...
                app.CorrectParams.SupportPoints); ...
                sprintf('Median window: %d', ...
                app.CorrectParams.MedianWindow); ...
                ''; ...
                'Press Trial correction to calculate a new preview.'});
            
        end

        % Button pushed function: DeletespectraButton
        function DeletespectraButtonPushed(app, event)
            if isempty(app.CubeOriginal)
                
                uialert( ...
                    app.UIFigure, ...
                    'No Raman scan is loaded.', ...
                    'No scan');
                
                return
            end
            
            app.openDeleteSpectrumReviewer();
        end

        % Value changed function: TextArea_5
        function TextArea_5ValueChanged(app, event)
            value = app.TextArea_5.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CosmicremovalTab = uitab(app.TabGroup);
            app.CosmicremovalTab.Title = 'Cosmic removal';
            app.GridLayout = uigridlayout(app.CosmicremovalTab);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Axis 1')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Axis 2')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.Tree = uitree(app.GridLayout);
            app.Tree.Multiselect = 'on';
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Tree.Editable = 'on';
            app.Node = uitreenode(app.Tree);
            app.Node.Text = 'Node';
            app.Node2 = uitreenode(app.Node);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.Node);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.Node);
            app.Node4.Text = 'Node4';
            app.TextArea = uitextarea(app.GridLayout);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.DetectioncosmicspikePanel = uipanel(app.GridLayout);
            app.DetectioncosmicspikePanel.Title = 'Detection cosmic spike';
            app.GridLayout4 = uigridlayout(app.DetectioncosmicspikePanel);
            app.ModesDropDownLabel = uilabel(app.GridLayout4);
            app.ModesDropDownLabel.Text = 'Modes';
            app.ModesDropDown = uidropdown(app.GridLayout4);
            app.ModesDropDown.Items = {'', 'Heavy', 'Medium', 'Light', 'Manual'};
            app.ModesDropDown.ValueChangedFcn = createCallbackFcn(app, @ModesDropDownValueChanged, true);
            app.ModesDropDown.Value = '';
            app.CosmicdetectionButton = uibutton(app.GridLayout4, 'push');
            app.CosmicdetectionButton.ButtonPushedFcn = createCallbackFcn(app, @CosmicdetectionButtonPushed, true);
            app.CosmicdetectionButton.Text = 'Cosmic detection';
            app.Button_3 = uibutton(app.GridLayout4, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = fullfile(pathToMLAPP, 'yes.png');
            app.Button_3.Text = '';
            app.Button_2 = uibutton(app.GridLayout4, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'no.png');
            app.Button_2.Text = '';
            app.Button_4 = uibutton(app.GridLayout4, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = fullfile(pathToMLAPP, 'next.png');
            app.Button_4.Text = '';
            app.ConfirmfinalcosmiclistButton = uibutton(app.GridLayout4, 'push');
            app.ConfirmfinalcosmiclistButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmfinalcosmiclistButtonPushed, true);
            app.ConfirmfinalcosmiclistButton.Text = 'Confirm final cosmic list';
            app.ManualdetectButton = uibutton(app.GridLayout4, 'push');
            app.ManualdetectButton.ButtonPushedFcn = createCallbackFcn(app, @ManualdetectButtonPushed, true);
            app.ManualdetectButton.Text = 'Manual detect';
            app.Button = uibutton(app.GridLayout4, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'back.png');
            app.Button.Text = '';
            app.DeletespectraButton = uibutton(app.GridLayout4, 'push');
            app.DeletespectraButton.ButtonPushedFcn = createCallbackFcn(app, @DeletespectraButtonPushed, true);
            app.DeletespectraButton.Text = 'Delete spectra';
            app.CorrectioncosmicspikePanel = uipanel(app.GridLayout);
            app.CorrectioncosmicspikePanel.Title = 'Correction cosmic spike';
            app.GridLayout5 = uigridlayout(app.CorrectioncosmicspikePanel);
            app.MethodsDropDown_2Label = uilabel(app.GridLayout5);
            app.MethodsDropDown_2Label.Text = 'Methods';
            app.MethodsDropDown_2 = uidropdown(app.GridLayout5);
            app.MethodsDropDown_2.ValueChangedFcn = createCallbackFcn(app, @MethodsDropDown_2ValueChanged, true);
            app.TrialcorrectionButton = uibutton(app.GridLayout5, 'push');
            app.TrialcorrectionButton.ButtonPushedFcn = createCallbackFcn(app, @TrialcorrectionButtonPushed, true);
            app.TrialcorrectionButton.Text = 'Trial correction';
            app.ApplyforallButton = uibutton(app.GridLayout5, 'push');
            app.ApplyforallButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyforallButtonPushed, true);
            app.ApplyforallButton.Text = 'Apply for all';
            app.ReviewButton = uibutton(app.GridLayout5, 'push');
            app.ReviewButton.ButtonPushedFcn = createCallbackFcn(app, @ReviewButtonPushed, true);
            app.ReviewButton.Text = 'Review';
            app.ModesDropDown_2Label = uilabel(app.GridLayout5);
            app.ModesDropDown_2Label.Text = 'Modes';
            app.ModesDropDown_2 = uidropdown(app.GridLayout5);
            app.ModesDropDown_2.Items = {'', 'Heavy', 'Medium', 'Light', 'Manual'};
            app.ModesDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ModesDropDown_2ValueChanged, true);
            app.ModesDropDown_2.Value = '';
            app.TextArea_5 = uitextarea(app.GridLayout);
            app.TextArea_5.ValueChangedFcn = createCallbackFcn(app, @TextArea_5ValueChanged, true);
            app.GridLayout12 = uigridlayout(app.GridLayout);
            app.FinishButton = uibutton(app.GridLayout12, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.SaveimagesButton = uibutton(app.GridLayout12, 'push');
            app.SaveimagesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveimagesButtonPushed, true);
            app.SaveimagesButton.Text = 'Save images';
            app.ArtifactremovalTab = uitab(app.TabGroup);
            app.ArtifactremovalTab.Title = 'Artifact removal';
            app.GridLayout6 = uigridlayout(app.ArtifactremovalTab);
            app.UIAxes3 = uiaxes(app.GridLayout6);
            title(app.UIAxes3, 'Axis 1')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.ButtonDownFcn = createCallbackFcn(app, @UIAxes3ButtonDown, true);
            app.UIAxes4 = uiaxes(app.GridLayout6);
            title(app.UIAxes4, 'Axis 2')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.ButtonDownFcn = createCallbackFcn(app, @UIAxes4ButtonDown, true);
            app.ParametersPanel = uipanel(app.GridLayout6);
            app.ParametersPanel.Title = 'Parameters';
            app.GridLayout7 = uigridlayout(app.ParametersPanel);
            app.ModeDropDownLabel = uilabel(app.GridLayout7);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.GridLayout7);
            app.ModeDropDown.Items = {'', 'Option 2', 'Option 3', 'Option 4'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Value = '';
            app.TextArea_2 = uitextarea(app.GridLayout7);
            app.TextArea_2.ValueChangedFcn = createCallbackFcn(app, @TextArea_2ValueChanged, true);
            app.DetectartifactButton = uibutton(app.GridLayout7, 'push');
            app.DetectartifactButton.ButtonPushedFcn = createCallbackFcn(app, @DetectartifactButtonPushed, true);
            app.DetectartifactButton.Text = 'Detect artifact';
            app.SaveimagesButton_2 = uibutton(app.GridLayout7, 'push');
            app.SaveimagesButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveimagesButton_2Pushed, true);
            app.SaveimagesButton_2.Text = 'Save images';
            app.FinishButton_2 = uibutton(app.GridLayout7, 'push');
            app.FinishButton_2.ButtonPushedFcn = createCallbackFcn(app, @FinishButton_2Pushed, true);
            app.FinishButton_2.Text = 'Finish';
            app.ViewDropDownLabel = uilabel(app.GridLayout7);
            app.ViewDropDownLabel.Text = 'View';
            app.ViewDropDown = uidropdown(app.GridLayout7);
            app.ViewDropDown.Items = {'', 'Option 2', 'Option 3', 'Option 4'};
            app.ViewDropDown.ValueChangedFcn = createCallbackFcn(app, @ViewDropDownValueChanged, true);
            app.ViewDropDown.Value = '';
            app.ColorsDropDownLabel = uilabel(app.GridLayout7);
            app.ColorsDropDownLabel.Text = 'Colors';
            app.ColorsDropDown = uidropdown(app.GridLayout7);
            app.ColorsDropDown.Items = {'', 'Option 2', 'Option 3', 'Option 4'};
            app.ColorsDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorsDropDownValueChanged, true);
            app.ColorsDropDown.Value = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = scanpretreat

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end