classdef scanspectra < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout4                     matlab.ui.container.GridLayout
        MainhomeButton                  matlab.ui.control.Button
        FinishButton                    matlab.ui.control.Button
        Panel_2                         matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        Panel                           matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        LoadRamanscanButton             matlab.ui.control.Button
        ScananalysisButton              matlab.ui.control.Button
        SinglespectraanalysisButton     matlab.ui.control.Button
        SinglespectraextractionButton   matlab.ui.control.Button
        ScanpretreatButton              matlab.ui.control.Button
        TextArea_2                      matlab.ui.control.TextArea
        TextArea                        matlab.ui.control.TextArea
        ActivityhistoryLabel            matlab.ui.control.Label
        ScanningtospectraanalysisLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainHomeApp
        scanPretreatApp
        scanSingleExtractApp
        loadScanApp
        unmixingApp
        
        % Result returned after finishing a pretreat stage
        PretreatResult struct = struct()
        
        % =============================================================
        % ORIGINAL SCAN
        %
        % Dữ liệu gốc được nạp hoặc chuẩn bị bởi Loadscan.
        % Biến này không được ghi đè bởi Cosmic, Artifact, Background.
        % =============================================================
        OriginalLoadedScan struct = struct()
        
        % =============================================================
        % ACTIVE SCAN
        %
        % Dữ liệu đang được dùng tại stage hiện tại.
        % Có thể thay đổi sau Cosmic, Artifact hoặc Background.
        % =============================================================
        ActiveLoadedScan struct = struct()
        
        % ===== RamanCube folders received from Home =====
        ProjectRoot char = ''
        DataInputRoot char = ''
        DataOutputRoot char = ''
        
        ScanInputRoot char = ''
        ScanOutputRoot char = ''
        
        ScanWorkspaceRoot char = ''
        
        HomeHistoryRoot char = ''
        HomeHistoryFile char = ''
        HomeSessionSummaryFile char = ''
        
        % ===== Session received from Mainhome =====
        SessionAuthenticated logical = false
        SessionRole char = ''
        SessionUserName char = ''
        SessionEmail char = ''
        SessionID char = ''
        SessionLogPath char = ''
        MainhomeLoginTime char = ''
    end
    
    methods (Access = public)
        
        function returnToMainHomeFromPretreat(app)
            
            % =========================================================
            % RETURN FROM SCANPRETREAT TO MAIN HOME
            % =========================================================
            
            try
                app.addHistory( ...
                    'Returned to Mainhome from scanpretreat.');
                
                if ~isempty(app.mainHomeApp) && ...
                        isvalid(app.mainHomeApp)
                    
                    if ismethod( ...
                            app.mainHomeApp, ...
                            'showHomeFromChild')
                        
                        app.mainHomeApp.showHomeFromChild( ...
                            'Raman scan');
                        
                    elseif isprop(app.mainHomeApp, 'UIFigure') && ...
                            isvalid(app.mainHomeApp.UIFigure)
                        
                        app.mainHomeApp.UIFigure.Visible = 'on';
                        
                        try
                            figure(app.mainHomeApp.UIFigure);
                        catch
                        end
                    end
                    
                else
                    % Fallback only when the original Home object no
                    % longer exists.
                    try
                        homeApp = Home();
                    catch
                        homeApp = Mainhome();
                    end
                    
                    homeApp.UIFigure.Visible = 'on';
                end
                
                app.UIFigure.Visible = 'off';
                
            catch ME
                uialert( ...
                    app.UIFigure, ...
                    ME.message, ...
                    'Return Home error');
            end
        end


        function receiveBackgroundAndOpenSingleExtraction(app, payload)
            
            % =============================================================
            % VALIDATE
            % =============================================================
            
            if nargin < 2 || ...
                    isempty(payload) || ...
                    ~isstruct(payload)
                
                error( ...
                    'Invalid Background removal payload.');
            end
            
            requiredFields = { ...
                'ScanName', ...
                'ScanID', ...
                'Cube', ...
                'Wavenumber', ...
                'ValidMask'};
            
            for k = 1:numel(requiredFields)
                
                if ~isfield(payload, requiredFields{k})
                    
                    error( ...
                        'Background payload is missing field: %s', ...
                        requiredFields{k});
                end
            end
            
            if isempty(payload.Cube) || ...
                    ndims(payload.Cube) ~= 3
                
                error( ...
                    'Background payload must contain a 3-D Raman cube.');
            end
            
            [ny, nx, nWn] = ...
                size(payload.Cube);
            
            if numel(payload.Wavenumber) ~= nWn
                
                error( ...
                    ['Background payload wavenumber does not match ' ...
                    'the cube spectral dimension.']);
            end
            
            if isempty(payload.ValidMask) || ...
                    ~isequal( ...
                    size(payload.ValidMask), ...
                    [ny nx])
                
                error( ...
                    ['Background payload ValidMask must match the ' ...
                    'spatial scan size.']);
            end
            
            payload.Cube = ...
                double(payload.Cube);
            
            payload.Wavenumber = ...
                double(payload.Wavenumber(:));
            
            payload.ValidMask = ...
                logical(payload.ValidMask);
            
            % =============================================================
            % RESOLVE WORKSPACE
            % =============================================================
            
            scanID = ...
                app.makeSafeTextForFileName( ...
                payload.ScanID);
            
            workspacePath = '';
            
            if isfield(payload, 'WorkspacePath') && ...
                    strlength(string(payload.WorkspacePath)) > 0
                
                workspacePath = ...
                    char(string(payload.WorkspacePath));
            end
            
            if isempty(workspacePath)
                
                workspacePath = ...
                    fullfile( ...
                    app.ScanWorkspaceRoot, ...
                    scanID);
            end
            
            singleExtractRoot = ...
                fullfile( ...
                workspacePath, ...
                '04_single_extract');
            
            if ~isfolder(singleExtractRoot)
                mkdir(singleExtractRoot);
            end
            
            % =============================================================
            % STORAGE CONTRACT FOR SINGLE_EXTRACT
            % =============================================================
            
            payload.ProjectRoot = ...
                string(app.ProjectRoot);
            
            payload.DataInputRoot = ...
                string(app.DataInputRoot);
            
            payload.DataOutputRoot = ...
                string(app.DataOutputRoot);
            
            payload.ScanInputRoot = ...
                string(app.ScanInputRoot);
            
            payload.ScanOutputRoot = ...
                string(app.ScanOutputRoot);
            
            payload.WorkspacePath = ...
                string(workspacePath);
            
            payload.ManifestPath = ...
                string( ...
                fullfile( ...
                workspacePath, ...
                'manifest.mat'));
            
            payload.OutputRoot = ...
                string(singleExtractRoot);
            
            payload.SingleInputRoot = ...
                string( ...
                fullfile( ...
                app.DataInputRoot, ...
                'single'));
            
            payload.ReadableRoot = ...
                string( ...
                fullfile( ...
                app.ScanInputRoot, ...
                'READABLE'));
            
            % =============================================================
            % STORE ACTIVE DATA
            % =============================================================
            
            % app.ActiveLoadedScan = ...
            %     payload;
            %
            % app.addHistory(sprintf( ...
            %     'Background result received for extraction: %s', ...
            %     char(payload.ScanName)));
            %
            % app.addHistory(sprintf( ...
            %     'Cell pixels available for extraction: %d', ...
            %     nnz(payload.ValidMask)));
            %
            % % =============================================================
            % % OPEN CORRECT CLASS
            % % =============================================================
            %
            % if isempty(app.scanSingleExtractApp) || ...
            %         ~isvalid(app.scanSingleExtractApp)
            %
            %     app.scanSingleExtractApp = ...
            %         single_extract();
            % end
            %
            % if ~ismethod( ...
            %         app.scanSingleExtractApp, ...
            %         'receiveSegmentationPayload')
            %
            %     error( ...
            %         ['single_extract does not contain ' ...
            %         'receiveSegmentationPayload().']);
            % end
            %
            % app.scanSingleExtractApp.receiveSegmentationPayload( ...
            %     payload, ...
            %     app);
            %
            % app.scanSingleExtractApp.UIFigure.Visible = ...
            %     'on';
            %
            % try
            %     figure(app.scanSingleExtractApp.UIFigure);
            % catch
            % end
            %
            % app.UIFigure.Visible = ...
            %     'off';
            %
            % app.addHistory( ...
            %     ['Opened single_extract from confirmed ' ...
            %     'Background result.']);
            
            % =============================================================
            % STORE ACTIVE SCAN
            % =============================================================
            
            app.ActiveLoadedScan = ...
                payload;
            
            
            % =============================================================
            % HISTORY
            %
            % IMPORTANT:
            % This is NOT yet a Cell-Background result.
            % It is a pretreated / artifact-clean scan entering
            % Cell-Background segmentation.
            % =============================================================
            
            app.addHistory(sprintf( ...
                'Prepared scan sent to Cell-Background segmentation: %s', ...
                char(payload.ScanName)));
            
            if isfield(payload, 'ValidMask') && ...
                    ~isempty(payload.ValidMask)
                
                app.addHistory(sprintf( ...
                    'Analytically valid pixels available: %d', ...
                    nnz(payload.ValidMask)));
                
            elseif isfield(payload, 'ValidMask2D') && ...
                    ~isempty(payload.ValidMask2D)
                
                app.addHistory(sprintf( ...
                    'Analytically valid pixels available: %d', ...
                    nnz(payload.ValidMask2D)));
                
            end
            
            
            % =============================================================
            % OPEN SINGLE_EXTRACT
            % =============================================================
            
            if isempty(app.scanSingleExtractApp) || ...
                    ~isvalid(app.scanSingleExtractApp)
                
                app.scanSingleExtractApp = ...
                    single_extract();
                
            end
            
            
            % =============================================================
            % VERIFY LIVE-SCAN ENTRY POINT
            % =============================================================
            
            if ~ismethod( ...
                    app.scanSingleExtractApp, ...
                    'receiveScanForSegmentation')
                
                error( ...
                    'scanspectra:MissingSingleExtractReceiver', ...
                    ['single_extract does not contain ' ...
                    'receiveScanForSegmentation().']);
                
            end
            
            
            % =============================================================
            % SEND PRETREATED SCAN
            % =============================================================
            
            app.scanSingleExtractApp.receiveScanForSegmentation( ...
                payload, ...
                app);
            
            
            % =============================================================
            % SHOW SINGLE_EXTRACT
            % =============================================================
            
            app.scanSingleExtractApp.UIFigure.Visible = ...
                'on';
            
            try
                
                figure( ...
                    app.scanSingleExtractApp.UIFigure);
                
            catch
            end
            
            
            % =============================================================
            % HIDE SCANSPECTRA
            % =============================================================
            
            app.UIFigure.Visible = ...
                'off';
            
            
            % =============================================================
            % HISTORY
            % =============================================================
            
            app.addHistory( ...
                ['Opened single_extract at ' ...
                'Cell-Background segmentation.']);
            
        end
        
        function receivePreparedScan(app, payload)
            
            % =============================================================
            % VALIDATE PAYLOAD
            % =============================================================
            if nargin < 2 || ...
                    isempty(payload) || ...
                    ~isstruct(payload)
                
                error( ...
                    'Invalid prepared-scan payload received from Loadscan.');
            end
            
            requiredFields = { ...
                'ScanName', ...
                'ScanID', ...
                'Cube', ...
                'Wavenumber', ...
                'ValidMask', ...
                'SourceMatPath'};
            
            for k = 1:numel(requiredFields)
                
                fieldName = requiredFields{k};
                
                if ~isfield(payload, fieldName)
                    
                    error( ...
                        'Prepared-scan payload is missing field: %s', ...
                        fieldName);
                end
            end
            
            % =============================================================
            % VALIDATE CUBE
            % =============================================================
            if isempty(payload.Cube) || ...
                    ndims(payload.Cube) ~= 3
                
                error( ...
                    'Prepared Raman scan must contain a 3-D cube.');
            end
            
            [ny, nx, nWn] = size(payload.Cube);
            
            % =============================================================
            % VALIDATE WAVENUMBER
            % =============================================================
            if isempty(payload.Wavenumber) || ...
                    numel(payload.Wavenumber) ~= nWn
                
                error([ ...
                    'Prepared scan wavenumber length does not match ' ...
                    'the cube spectral dimension.']);
            end
            
            % =============================================================
            % VALIDATE MASK
            % =============================================================
            if isempty(payload.ValidMask) || ...
                    ~isequal(size(payload.ValidMask), [ny nx])
                
                payload.ValidMask = ...
                    all(isfinite(payload.Cube), 3);
            else
                payload.ValidMask = ...
                    logical(payload.ValidMask) & ...
                    all(isfinite(payload.Cube), 3);
            end
            
            if ~any(payload.ValidMask(:))
                
                error( ...
                    'Prepared Raman scan contains no valid pixels.');
            end
            
            % =============================================================
            % NORMALIZE TYPES
            % =============================================================
            payload.Cube = ...
                double(payload.Cube);
            
            payload.Wavenumber = ...
                double(payload.Wavenumber(:));
            
            payload.ValidMask = ...
                logical(payload.ValidMask);
            
            payload.ScanName = ...
                string(payload.ScanName);
            
            payload.ScanID = ...
                string(payload.ScanID);
            
            payload.SourceMatPath = ...
                string(payload.SourceMatPath);
            % =============================================================
            % CREATE OR REUSE SCAN WORKSPACE
            % =============================================================
            payload = app.ensureScanWorkspace(payload);
            
            if ~isfield(payload, 'Stage') || ...
                    strlength(string(payload.Stage)) == 0
                
                payload.Stage = ...
                    "Prepared raw scan";
            else
                payload.Stage = ...
                    string(payload.Stage);
            end
            
            % =============================================================
            % STORE AS ORIGINAL AND ACTIVE SCAN
            %
            % OriginalLoadedScan:
            %   Bản dữ liệu gốc cố định của toàn workflow.
            %
            % ActiveLoadedScan:
            %   Bản dữ liệu đang được sử dụng tại stage hiện tại.
            % =============================================================
            payload.Stage = "Original prepared Raman scan";
            payload.SourceStage = "Original";
            
            app.OriginalLoadedScan = payload;
            app.ActiveLoadedScan = payload;
            
            % =============================================================
            % HISTORY
            % =============================================================
            app.addHistory(sprintf( ...
                'Prepared Raman scan received from Loadscan: %s', ...
                char(payload.ScanName)));
            
            app.addHistory(sprintf( ...
                'Prepared cube size: %d x %d x %d', ...
                ny, nx, nWn));
            
            app.addHistory(sprintf( ...
                'Valid pixels: %d / %d', ...
                nnz(payload.ValidMask), ...
                numel(payload.ValidMask)));
            
            app.addHistory( ...
                ['Prepared MAT: ' char(payload.SourceMatPath)]);
            
            if isfield(payload, 'WorkspacePath') && ...
                    strlength(string(payload.WorkspacePath)) > 0
                
                app.addHistory( ...
                    ['Workspace: ' char(payload.WorkspacePath)]);
            end
            
            app.addHistory( ...
                'Next recommended step: Scan pre-treat.');
            
            drawnow limitrate;
        end
        
        
        function receiveHomeSession(app, sessionInfo, mainHomeApp)
            
            % =============================================================
            % VALIDATE INPUT
            % =============================================================
            if nargin < 2 || isempty(sessionInfo) || ~isstruct(sessionInfo)
                
                error([ ...
                    'A valid Home sessionInfo structure is required ', ...
                    'to open Raman scan mode.']);
                
            end
            
            if nargin >= 3
                app.mainHomeApp = mainHomeApp;
            else
                app.mainHomeApp = [];
            end
            
            % =============================================================
            % VALIDATE SELECTED MODE
            % =============================================================
            if isfield(sessionInfo, 'DataType') && ...
                    ~isempty(sessionInfo.DataType)
                
                receivedMode = char(sessionInfo.DataType);
                
                if ~strcmp(receivedMode, 'Raman scan')
                    
                    error( ...
                        'scanspectra received invalid mode: %s', ...
                        receivedMode);
                    
                end
            end
            
            % =============================================================
            % RECEIVE USER IDENTITY
            % =============================================================
            app.SessionUserName = app.readSessionTextField( ...
                sessionInfo, ...
                'User', ...
                'unknown_user');
            
            app.SessionEmail = app.readSessionTextField( ...
                sessionInfo, ...
                'Email', ...
                '');
            
            app.SessionRole = app.readSessionTextField( ...
                sessionInfo, ...
                'Role', ...
                'unknown_role');
            
            app.MainhomeLoginTime = app.readSessionTextField( ...
                sessionInfo, ...
                'LoginTime', ...
                datestr(now, 'yyyy-mm-dd HH:MM:SS'));
            
            % =============================================================
            % INHERIT THE SAME HOME SESSION ID
            % Do not create a separate login/session identity here.
            % =============================================================
            app.SessionID = app.readSessionTextField( ...
                sessionInfo, ...
                'SessionID', ...
                '');
            
            if isempty(app.SessionID)
                
                timeID = datestr(now, 'yyyymmdd_HHMMSS');
                safeRole = app.makeSafeTextForFileName(app.SessionRole);
                safeUser = app.makeSafeTextForFileName(app.SessionUserName);
                
                app.SessionID = sprintf( ...
                    '%s_%s_%s', ...
                    timeID, ...
                    safeRole, ...
                    safeUser);
            end
            
            % =============================================================
            % RECEIVE PROJECT ROOTS FROM HOME
            % =============================================================
            app.ProjectRoot = app.readSessionTextField( ...
                sessionInfo, ...
                'ProjectRoot', ...
                '');
            
            app.DataInputRoot = app.readSessionTextField( ...
                sessionInfo, ...
                'InputRoot', ...
                '');
            
            app.DataOutputRoot = app.readSessionTextField( ...
                sessionInfo, ...
                'OutputRoot', ...
                '');
            
            % =============================================================
            % RESOLVE SCAN FOLDERS
            % Prefer explicit fields supplied by Home.
            % =============================================================
            app.ScanInputRoot = app.readSessionTextField( ...
                sessionInfo, ...
                'ScanInputRoot', ...
                '');
            
            if isempty(app.ScanInputRoot) && ~isempty(app.DataInputRoot)
                
                app.ScanInputRoot = fullfile( ...
                    app.DataInputRoot, ...
                    'scan');
                
            end
            
            app.ScanOutputRoot = app.readSessionTextField( ...
                sessionInfo, ...
                'ScanOutputRoot', ...
                '');
            
            if isempty(app.ScanOutputRoot) && ~isempty(app.DataOutputRoot)
                
                app.ScanOutputRoot = fullfile( ...
                    app.DataOutputRoot, ...
                    'scan');
                
            end
            
            app.ScanWorkspaceRoot = app.readSessionTextField( ...
                sessionInfo, ...
                'ScanWorkspaceRoot', ...
                '');
            
            if isempty(app.ScanWorkspaceRoot) && ...
                    ~isempty(app.ScanOutputRoot)
                
                app.ScanWorkspaceRoot = fullfile( ...
                    app.ScanOutputRoot, ...
                    'workspaces');
            end
            
            if ~isfolder(app.ScanWorkspaceRoot)
                mkdir(app.ScanWorkspaceRoot);
            end
            
            % =============================================================
            % RECEIVE HOME HISTORY PATHS
            % =============================================================
            app.HomeHistoryRoot = app.readSessionTextField( ...
                sessionInfo, ...
                'HistoryRoot', ...
                '');
            
            app.HomeHistoryFile = app.readSessionTextField( ...
                sessionInfo, ...
                'HistoryFile', ...
                '');
            
            app.HomeSessionSummaryFile = app.readSessionTextField( ...
                sessionInfo, ...
                'SessionSummaryFile', ...
                '');
            
            % Child-specific detailed scan log.
            if isempty(app.HomeHistoryRoot)
                
                app.HomeHistoryRoot = fullfile( ...
                    app.DataOutputRoot, ...
                    '_history');
                
            end
            
            scanHistoryFolder = fullfile( ...
                app.HomeHistoryRoot, ...
                'scan');
            
            if ~isfolder(scanHistoryFolder)
                mkdir(scanHistoryFolder);
            end
            
            app.SessionLogPath = fullfile( ...
                scanHistoryFolder, ...
                [app.makeSafeTextForFileName(app.SessionID) ...
                '_raman_scan_history.txt']);
            
            % =============================================================
            % FINAL VALIDATION
            % =============================================================
            if isempty(app.DataInputRoot) || ...
                    isempty(app.DataOutputRoot) || ...
                    isempty(app.ScanInputRoot) || ...
                    isempty(app.ScanOutputRoot)
                
                error([ ...
                    'The Raman scan folder configuration received ', ...
                    'from Home is incomplete.']);
                
            end
            
            app.ensureScanHomeFolders();
            
            % =============================================================
            % ACTIVATE SESSION
            % =============================================================
            app.SessionAuthenticated = true;
            app.setScanControlsEnabled(true);
            
            app.writeSessionHeader( ...
                'Opened Raman scan mode from Home');
            
            app.updateCurrentUserBox();
            
            app.addHistory( ...
                'Opened Raman scan mode from Home.');
            
            app.addHistory( ...
                ['Home session ID: ' app.SessionID]);
            
            app.addHistory( ...
                ['Input scan folder: ' app.ScanInputRoot]);
            
            app.addHistory( ...
                ['Output scan folder: ' app.ScanOutputRoot]);
            
            app.wireRuntimeCallbacks();
            
            % Also write into central Home audit log.
            app.logToHome( ...
                'Open Raman scan', ...
                sprintf( ...
                'input=%s; output=%s', ...
                app.ScanInputRoot, ...
                app.ScanOutputRoot));
            
            msgbox( ...
                sprintf([ ...
                'Raman scan workspace is ready.\n\n' ...
                'User: %s\n' ...
                'Role: %s\n' ...
                'Session ID: %s\n\n' ...
                'Input folder:\n%s\n\n' ...
                'Output folder:\n%s'], ...
                app.SessionUserName, ...
                app.SessionRole, ...
                app.SessionID, ...
                app.ScanInputRoot, ...
                app.ScanOutputRoot), ...
                'Raman scan');
        end
        
        
        function updateCurrentUserBox(app)
            if app.SessionAuthenticated
                app.TextArea_2.Value = {app.SessionUserName};
            else
                app.TextArea_2.Value = {'No Mainhome session'};
            end
            drawnow limitrate;
        end
        
        
        function receiveLoadedScanFromPretreat(app, payload)
            
            % =============================================================
            % RECEIVE ACTIVE PRETREATED SCAN FROM SCANPRETREAT
            %
            % Required:
            %   Cube       = committed pretreated cube
            %   RawCube    = original raw acquisition cube
            %
            % RawCube must be preserved so single_extract can later export
            % both raw and pretreated spectra for every selected cluster pixel.
            % =============================================================
            
            % =============================================================
            % 1. VALIDATE PAYLOAD
            % =============================================================
            
            if nargin < 2 || ...
                    isempty(payload) || ...
                    ~isstruct(payload)
                
                error( ...
                    'Invalid loaded-scan payload received from scanpretreat.');
            end
            
            requiredFields = { ...
                'ScanName', ...
                'ScanID', ...
                'Cube', ...
                'RawCube', ...
                'Wavenumber', ...
                'ValidMask'};
            
            for k = 1:numel(requiredFields)
                
                fieldName = ...
                    requiredFields{k};
                
                if ~isfield(payload, fieldName) || ...
                        isempty(payload.(fieldName))
                    
                    error( ...
                        'Loaded-scan payload is missing field: %s', ...
                        fieldName);
                end
            end
            
            % =============================================================
            % 2. VALIDATE PRETREATED CUBE
            % =============================================================
            
            cube = ...
                double(payload.Cube);
            
            if isempty(cube) || ...
                    ndims(cube) ~= 3
                
                error( ...
                    'The active pretreated scan must contain a 3-D Raman cube.');
            end
            
            [ny, nx, nWn] = ...
                size(cube);
            
            % =============================================================
            % 3. VALIDATE ORIGINAL RAW CUBE
            % =============================================================
            
            rawCube = ...
                double(payload.RawCube);
            
            if isempty(rawCube) || ...
                    ndims(rawCube) ~= 3
                
                error( ...
                    'The pretreat payload RawCube must be a non-empty 3-D cube.');
            end
            
            if ~isequal(size(rawCube), size(cube))
                
                error([ ...
                    'RawCube and Cube dimensions do not match.' ...
                    newline ...
                    'RawCube: %s' ...
                    newline ...
                    'Cube: %s'], ...
                    mat2str(size(rawCube)), ...
                    mat2str(size(cube)));
            end
            
            % =============================================================
            % 4. VALIDATE WAVENUMBER
            % =============================================================
            
            wn = ...
                double(payload.Wavenumber(:));
            
            if isempty(wn) || ...
                    numel(wn) ~= nWn
                
                error( ...
                    ['The loaded scan wavenumber axis does not match ' ...
                    'the cube spectral dimension.']);
            end
            
            % =============================================================
            % 5. FINITE MASK
            %
            % The analytical valid mask is based on the committed pretreated
            % cube because this is the cube used for segmentation.
            % =============================================================
            
            finiteMask = ...
                all(isfinite(cube), 3);
            
            % =============================================================
            % 6. ARTIFACT MASK
            % =============================================================
            
            if isfield(payload, 'ArtifactMask2D') && ...
                    isequal(size(payload.ArtifactMask2D), [ny nx])
                
                artifactMask = ...
                    logical(payload.ArtifactMask2D);
                
            else
                
                artifactMask = ...
                    false(ny, nx);
            end
            
            % =============================================================
            % 7. VALID MASK
            % =============================================================
            
            if isempty(payload.ValidMask) || ...
                    ~isequal(size(payload.ValidMask), [ny nx])
                
                validMask = ...
                    finiteMask & ...
                    ~artifactMask;
                
            else
                
                validMask = ...
                    logical(payload.ValidMask) & ...
                    finiteMask & ...
                    ~artifactMask;
            end
            
            if ~any(validMask(:))
                
                error( ...
                    'The loaded scan contains no valid pixels.');
            end
            
            % =============================================================
            % 8. NORMALIZE CORE PAYLOAD FIELDS
            % =============================================================
            
            payload.Cube = ...
                cube;
            
            payload.RawCube = ...
                rawCube;
            
            payload.Wavenumber = ...
                wn;
            
            payload.ValidMask = ...
                logical(validMask);
            
            payload.ValidMask2D = ...
                logical(validMask);
            
            payload.ArtifactMask2D = ...
                logical(artifactMask);
            
            payload.ScanName = ...
                string(payload.ScanName);
            
            payload.ScanID = ...
                string(payload.ScanID);
            
            % =============================================================
            % 9. DATA DEFINITIONS
            % =============================================================
            
            if ~isfield(payload, 'RawCubeDefinition') || ...
                    strlength(string(payload.RawCubeDefinition)) == 0
                
                payload.RawCubeDefinition = ...
                    ['Original Raman acquisition cube before cosmic ' ...
                    'correction and artifact masking.'];
            end
            
            if ~isfield(payload, 'PretreatedCubeDefinition') || ...
                    strlength(string(payload.PretreatedCubeDefinition)) == 0
                
                payload.PretreatedCubeDefinition = ...
                    ['Committed Raman cube used for PCA/K-means ' ...
                    'segmentation.'];
            end
            
            if ~isfield(payload, 'ArtifactHandling') || ...
                    strlength(string(payload.ArtifactHandling)) == 0
                
                payload.ArtifactHandling = ...
                    ['Artifact pixels are excluded through ValidMask2D ' ...
                    'and ArtifactMask2D. Display-only inpainting is not ' ...
                    'used as analytical spectral data.'];
            end
            
            payload.RawAndPretreatedPixelAlignment = ...
                ['RawCube(y,x,k) and Cube(y,x,k) refer to the same ' ...
                'spatial pixel and wavenumber channel.'];
            
            payload.CubeDimensionOrder = ...
                'row x column x wavenumber';
            
            % =============================================================
            % 10. STAGE METADATA
            % =============================================================
            
            if ~isfield(payload, 'Stage') || ...
                    strlength(string(payload.Stage)) == 0
                
                payload.Stage = ...
                    "Pretreated active scan";
                
            else
                
                payload.Stage = ...
                    string(payload.Stage);
            end
            
            if ~isfield(payload, 'SourceStage') || ...
                    strlength(string(payload.SourceStage)) == 0
                
                if nnz(artifactMask) > 0
                    
                    payload.SourceStage = ...
                        "Artifact";
                    
                else
                    
                    payload.SourceStage = ...
                        "Cosmic";
                end
                
            else
                
                payload.SourceStage = ...
                    string(payload.SourceStage);
            end
            
            if ~isfield(payload, 'SourceMatPath')
                
                payload.SourceMatPath = ...
                    "";
                
            else
                
                payload.SourceMatPath = ...
                    string(payload.SourceMatPath);
            end
            
            if ~isfield(payload, 'OriginalSourceMatPath')
                
                if isfield(app.OriginalLoadedScan, 'SourceMatPath')
                    
                    payload.OriginalSourceMatPath = ...
                        string(app.OriginalLoadedScan.SourceMatPath);
                    
                else
                    
                    payload.OriginalSourceMatPath = ...
                        "";
                end
            else
                
                payload.OriginalSourceMatPath = ...
                    string(payload.OriginalSourceMatPath);
            end
            
            if ~isfield(payload, 'OutputRoot') || ...
                    strlength(string(payload.OutputRoot)) == 0
                
                payload.OutputRoot = ...
                    string(app.ScanOutputRoot);
                
            else
                
                payload.OutputRoot = ...
                    string(payload.OutputRoot);
            end
            
            % =============================================================
            % 11. PAYLOAD SUMMARY
            % =============================================================
            
            payload.CubeSize = ...
                [ny nx nWn];
            
            payload.RawCubeSize = ...
                size(rawCube);
            
            payload.ValidPixelCount = ...
                nnz(validMask);
            
            payload.ArtifactPixelCount = ...
                nnz(artifactMask);
            
            payload.TotalPixelCount = ...
                numel(validMask);
            
            payload.RawCubeIncluded = ...
                true;
            
            payload.PretreatedCubeIncluded = ...
                true;
            
            payload.DisplayInpaintingTransferred = ...
                false;
            
            payload.ReceivedAtScanSpectra = ...
                datestr(now, 'yyyy-mm-dd HH:MM:SS');
            
            % =============================================================
            % 12. STORE AS ACTIVE SCAN
            %
            % OriginalLoadedScan remains unchanged.
            % ActiveLoadedScan contains both RawCube and Cube.
            % =============================================================
            
            app.ActiveLoadedScan = ...
                payload;
            
            % =============================================================
            % 13. HISTORY
            % =============================================================
            
            try
                
                app.addHistory(sprintf( ...
                    'Active scan received from scanpretreat: %s', ...
                    char(payload.ScanName)));
                
                app.addHistory(sprintf( ...
                    'Pretreat stage: %s', ...
                    char(payload.Stage)));
                
                app.addHistory(sprintf( ...
                    'Pretreated cube size: %d x %d x %d', ...
                    ny, nx, nWn));
                
                app.addHistory( ...
                    'Original RawCube preserved in ActiveLoadedScan.');
                
                app.addHistory(sprintf( ...
                    'Active valid pixels: %d / %d', ...
                    nnz(validMask), ...
                    numel(validMask)));
                
                app.addHistory(sprintf( ...
                    'Artifact pixels excluded: %d', ...
                    nnz(artifactMask)));
                
            catch MEhistory
                
                warning( ...
                    'RamanCube:ActiveScanHistoryFailed', ...
                    '%s', ...
                    MEhistory.message);
            end
            
            drawnow limitrate;
            
        end


        function addHistory(app, message)
            currentTime = datestr(now, 'yyyy-mm-dd HH:MM:SS');
            if app.SessionAuthenticated
                userTag = sprintf('%s | %s | %s', ...
                    app.SessionRole, app.SessionUserName, app.SessionEmail);
            else
                userTag = 'no Mainhome session';
            end
            newLine = sprintf('[%s] [%s] %s', currentTime, userTag, char(message));
            oldLines = app.TextArea.Value;
            
            if isempty(oldLines)
                app.TextArea.Value = {newLine};
            else
                if ischar(oldLines) || isstring(oldLines)
                    oldLines = cellstr(oldLines);
                end
                
                app.TextArea.Value = [oldLines(:); {newLine}];
            end
            app.appendHistoryToTxt(newLine);
            drawnow limitrate;
        end
        
        
        function receivePretreatResult(app, payload)
            % No login prompt here. This app must already have received Mainhome session.
            if ~app.ensureSessionIdentity('Receive pretreat result')
                return
            end
            app.PretreatResult = payload;
            if isfield(payload, 'Stage') && ~isempty(payload.Stage)
                stageName = char(payload.Stage);
            else
                stageName = 'Pretreat';
            end
            if isfield(payload, 'ScanName') && ~isempty(payload.ScanName)
                scanName = char(payload.ScanName);
            else
                scanName = 'unknown scan';
            end
            app.addHistory(sprintf('Received %s result: %s', stageName, scanName));
            if isfield(payload, 'ScanID') && ~isempty(payload.ScanID)
                app.addHistory(['Scan ID: ' char(payload.ScanID)]);
            end
            if isfield(payload, 'SavedMatPath') && ~isempty(payload.SavedMatPath)
                app.addHistory(['Saved MAT: ' payload.SavedMatPath]);
            end
            if isfield(payload, 'StatePath') && ~isempty(payload.StatePath)
                app.addHistory(['Saved state: ' payload.StatePath]);
            end
            if isfield(payload, 'EvidenceFolder') && ~isempty(payload.EvidenceFolder)
                app.addHistory(['Evidence folder: ' payload.EvidenceFolder]);
            end
            if isfield(payload, 'PayloadMode') && ~isempty(payload.PayloadMode)
                app.addHistory(['Payload mode: ' payload.PayloadMode]);
            end
            if isfield(payload, 'HeavyDataIncluded') && isequal(payload.HeavyDataIncluded, false)
                app.addHistory('Heavy data included: false. Downstream apps should load from SavedMatPath.');
            end
            drawnow limitrate;
        end
        
        
        function scanSession = buildScanHomeSessionInfo(app)
            
            % =============================================================
            % MAKE SURE SCAN FOLDERS ARE INITIALIZED
            % =============================================================
            
            app.ensureScanHomeFolders();
            
            
            % =============================================================
            % CRITICAL PATH VALIDATION
            % =============================================================
            
            if isempty(app.DataInputRoot)
                
                error( ...
                    'scanspectra:DataInputRootEmpty', ...
                    'DataInputRoot is empty.');
                
            end
            
            
            if isempty(app.DataOutputRoot)
                
                error( ...
                    'scanspectra:DataOutputRootEmpty', ...
                    'DataOutputRoot is empty.');
                
            end
            
            
            if isempty(app.ScanInputRoot)
                
                error( ...
                    'scanspectra:ScanInputRootEmpty', ...
                    'ScanInputRoot is empty.');
                
            end
            
            
            if isempty(app.ScanOutputRoot)
                
                error( ...
                    'scanspectra:ScanOutputRootEmpty', ...
                    'ScanOutputRoot is empty.');
                
            end
            
            
            % =============================================================
            % ALWAYS DERIVE WORKSPACE ROOT FROM SCAN OUTPUT ROOT
            %
            % Do not trust stale ScanWorkspaceRoot state.
            % =============================================================
            
            scanWorkspaceRoot = ...
                fullfile( ...
                app.ScanOutputRoot, ...
                'workspaces');
            
            
            % =============================================================
            % VALIDATE THAT PATH IS NOT JUST A DRIVE SPECIFIER
            % =============================================================
            
            if regexp( ...
                    scanWorkspaceRoot, ...
                    '^[A-Za-z]:$', ...
                    'once')
                
                error( ...
                    'scanspectra:InvalidWorkspaceRoot', ...
                    sprintf([ ...
                    'ScanWorkspaceRoot resolved only to a drive:\n\n%s\n\n' ...
                    'ScanOutputRoot was:\n%s'], ...
                    scanWorkspaceRoot, ...
                    app.ScanOutputRoot));
                
            end
            
            
            app.ScanWorkspaceRoot = ...
                scanWorkspaceRoot;
            
            
            % =============================================================
            % BUILD SESSION
            % =============================================================
            
            scanSession = struct();
            
            
            % =============================================================
            % IDENTITY
            % =============================================================
            
            scanSession.User = ...
                app.SessionUserName;
            
            scanSession.Email = ...
                app.SessionEmail;
            
            scanSession.Role = ...
                app.SessionRole;
            
            scanSession.SessionID = ...
                app.SessionID;
            
            scanSession.LoginTime = ...
                app.MainhomeLoginTime;
            
            
            % =============================================================
            % MODE
            % =============================================================
            
            scanSession.DataType = ...
                'Raman scan';
            
            
            % =============================================================
            % FOLDERS
            % =============================================================
            
            scanSession.ProjectRoot = ...
                app.ProjectRoot;
            
            scanSession.InputRoot = ...
                app.DataInputRoot;
            
            scanSession.OutputRoot = ...
                app.DataOutputRoot;
            
            scanSession.DataInputRoot = ...
                app.DataInputRoot;
            
            scanSession.DataOutputRoot = ...
                app.DataOutputRoot;
            
            scanSession.ScanInputRoot = ...
                app.ScanInputRoot;
            
            scanSession.ScanOutputRoot = ...
                app.ScanOutputRoot;
            
            scanSession.ScanWorkspaceRoot = ...
                scanWorkspaceRoot;
            
            
            % =============================================================
            % SINGLE DATASET ROOT
            % =============================================================
            
            scanSession.SingleInputRoot = ...
                fullfile( ...
                app.DataInputRoot, ...
                'single');
            
            
            % =============================================================
            % READABLE ROOT
            % =============================================================
            
            scanSession.ReadableRoot = ...
                fullfile( ...
                app.ScanInputRoot, ...
                'READABLE');
            
            
            % =============================================================
            % HISTORY
            % =============================================================
            
            scanSession.HomeHistoryRoot = ...
                app.HomeHistoryRoot;
            
            scanSession.HomeHistoryFile = ...
                app.HomeHistoryFile;
            
            scanSession.SessionLogPath = ...
                app.SessionLogPath;
            
            scanSession.OpenedAt = ...
                datestr( ...
                now, ...
                'yyyy-mm-dd HH:MM:SS');
            
            
            % =============================================================
            % DEBUG / SAFETY
            %
            % This will immediately reveal the actual paths being passed.
            % =============================================================
            
            fprintf('\n');
            fprintf('=============================================\n');
            fprintf('SCAN HOME SESSION PATHS\n');
            fprintf('=============================================\n');
            fprintf('DataInputRoot      = <%s>\n', ...
                scanSession.DataInputRoot);
            fprintf('DataOutputRoot     = <%s>\n', ...
                scanSession.DataOutputRoot);
            fprintf('ScanInputRoot      = <%s>\n', ...
                scanSession.ScanInputRoot);
            fprintf('ScanOutputRoot     = <%s>\n', ...
                scanSession.ScanOutputRoot);
            fprintf('ScanWorkspaceRoot  = <%s>\n', ...
                scanSession.ScanWorkspaceRoot);
            fprintf('ReadableRoot       = <%s>\n', ...
                scanSession.ReadableRoot);
            fprintf('SingleInputRoot    = <%s>\n', ...
                scanSession.SingleInputRoot);
            fprintf('=============================================\n\n');
            
        end


    end
    
    methods (Access = private)
        
        function ok = ensureSessionIdentity(app, requestedAction)
            
            ok = false;
            
            if nargin < 2 || isempty(requestedAction)
                requestedAction = 'Unknown action';
            end
            
            if app.SessionAuthenticated
                ok = true;
                return
            end
            
            % No local login anymore.
            % User must enter scanspectra through Mainhome.
            uialert(app.UIFigure, ...
                sprintf(['No Mainhome login session was found.\n\n' ...
                'Please return to Mainhome, log in, select Scan spectra, then press Access.\n\n' ...
                'Requested action:\n%s'], char(requestedAction)), ...
                'Mainhome session required');
            
            app.updateCurrentUserBox();
        end
        
        
        function initializeHomeAuditFolders(app)
            
            % Folder configuration must come from Home.
            % Never overwrite it with a hard-coded disk path.
            
            if isempty(app.DataInputRoot) && ~isempty(app.ProjectRoot)
                
                app.DataInputRoot = fullfile( ...
                    app.ProjectRoot, ...
                    'Data input');
                
            end
            
            if isempty(app.DataOutputRoot) && ~isempty(app.ProjectRoot)
                
                app.DataOutputRoot = fullfile( ...
                    app.ProjectRoot, ...
                    'Data output');
                
            end
            
            if isempty(app.ScanInputRoot) && ~isempty(app.DataInputRoot)
                
                app.ScanInputRoot = fullfile( ...
                    app.DataInputRoot, ...
                    'scan');
                
            end
            
            if isempty(app.ScanOutputRoot) && ~isempty(app.DataOutputRoot)
                
                app.ScanOutputRoot = fullfile( ...
                    app.DataOutputRoot, ...
                    'scan');
                
            end
            
            if isempty(app.HomeHistoryRoot) && ~isempty(app.DataOutputRoot)
                
                app.HomeHistoryRoot = fullfile( ...
                    app.DataOutputRoot, ...
                    '_history');
                
            end
            
            app.ensureScanHomeFolders();
        end
        
        
        function ensureScanHomeFolders(app)
            
            folders = { ...
                app.DataInputRoot, ...
                app.DataOutputRoot, ...
                app.ScanInputRoot, ...
                app.ScanOutputRoot, ...
                app.HomeHistoryRoot, ...
                fullfile(app.HomeHistoryRoot, 'scan')};
            
            for k = 1:numel(folders)
                
                folderPath = folders{k};
                
                if isempty(folderPath)
                    continue
                end
                
                if ~isfolder(folderPath)
                    mkdir(folderPath);
                end
            end
        end
        
        
        function writeSessionHeader(app, requestedAction)
            
            app.ensureScanHomeFolders();
            
            if isempty(app.SessionLogPath)
                
                scanHistoryFolder = fullfile( ...
                    app.HomeHistoryRoot, ...
                    'scan');
                
                if ~isfolder(scanHistoryFolder)
                    mkdir(scanHistoryFolder);
                end
                
                app.SessionLogPath = fullfile( ...
                    scanHistoryFolder, ...
                    [app.makeSafeTextForFileName(app.SessionID) ...
                    '_raman_scan_history.txt']);
            end
            
            fid = fopen(app.SessionLogPath, 'w');
            
            if fid < 0
                
                uialert(app.UIFigure, ...
                    ['Cannot create session log file: ' ...
                    app.SessionLogPath], ...
                    'Session log error');
                
                return
            end
            
            cleaner = onCleanup(@() fclose(fid));
            
            fprintf(fid, 'RAMANCUBE RAMAN SCAN SESSION LOG\n');
            fprintf(fid, '========================================\n');
            
            fprintf(fid, ...
                'Home session ID: %s\n', ...
                app.SessionID);
            
            fprintf(fid, ...
                'Scan workspace opened: %s\n', ...
                datestr(now, 'yyyy-mm-dd HH:MM:SS'));
            
            fprintf(fid, ...
                'Home login time: %s\n', ...
                app.MainhomeLoginTime);
            
            fprintf(fid, ...
                'Role: %s\n', ...
                app.SessionRole);
            
            fprintf(fid, ...
                'Name: %s\n', ...
                app.SessionUserName);
            
            fprintf(fid, ...
                'Email: %s\n', ...
                app.SessionEmail);
            
            fprintf(fid, ...
                'Mode: Raman scan\n');
            
            fprintf(fid, ...
                'Requested action: %s\n', ...
                char(requestedAction));
            
            fprintf(fid, ...
                'Project root: %s\n', ...
                app.ProjectRoot);
            
            fprintf(fid, ...
                'Data input root: %s\n', ...
                app.DataInputRoot);
            
            fprintf(fid, ...
                'Data output root: %s\n', ...
                app.DataOutputRoot);
            
            fprintf(fid, ...
                'Scan input folder: %s\n', ...
                app.ScanInputRoot);
            
            fprintf(fid, ...
                'Scan output folder: %s\n', ...
                app.ScanOutputRoot);
            
            fprintf(fid, ...
                'Computer: %s\n', ...
                getenv('COMPUTERNAME'));
            
            fprintf(fid, ...
                'Operating-system user: %s\n', ...
                getenv('USERNAME'));
            
            fprintf(fid, ...
                'MATLAB version: %s\n', ...
                version);
            
            fprintf(fid, '========================================\n\n');
        end
        
        
        function appendHistoryToTxt(app, lineText)
            
            try
                if isempty(app.SessionLogPath)
                    return
                end
                
                folderPath = fileparts(app.SessionLogPath);
                
                if ~exist(folderPath, 'dir')
                    mkdir(folderPath);
                end
                
                fid = fopen(app.SessionLogPath, 'a');
                
                if fid < 0
                    return
                end
                
                fprintf(fid, '%s\n', char(lineText));
                
                fclose(fid);
                
            catch
                try
                    if exist('fid', 'var') && fid > 0
                        fclose(fid);
                    end
                catch
                end
            end
        end
        
        
        function wireRuntimeCallbacks(app)
            
            % Runtime callback wiring only.
            % This does not rename or recreate components.
            try
                app.MainhomeButton.ButtonPushedFcn = createCallbackFcn(app, @MainhomeButtonPushed, true);
            catch
            end
        end
        
        
        function safeText = makeSafeTextForFileName(app, textIn)
            
            safeText = regexprep(char(textIn), '[^\w\-]', '_');
            
            if isempty(safeText)
                safeText = 'unknown';
            end
        end
        
        function payload = ensureScanWorkspace(app, payload)
            
            % =============================================================
            % ONE SCAN = ONE WORKSPACE = ONE MANIFEST
            % =============================================================
            
            if isempty(app.ScanWorkspaceRoot)
                
                if isempty(app.ScanOutputRoot)
                    
                    error( ...
                        ['ScanWorkspaceRoot and ScanOutputRoot are empty. ' ...
                        'Cannot create scan workspace.']);
                end
                
                app.ScanWorkspaceRoot = ...
                    fullfile( ...
                    app.ScanOutputRoot, ...
                    'workspaces');
            end
            
            if ~isfolder(app.ScanWorkspaceRoot)
                mkdir(app.ScanWorkspaceRoot);
            end
            
            % =============================================================
            % SCAN ID
            % =============================================================
            
            if ~isfield(payload, 'ScanID') || ...
                    strlength(string(payload.ScanID)) == 0
                
                if isfield(payload, 'ScanName') && ...
                        strlength(string(payload.ScanName)) > 0
                    
                    payload.ScanID = ...
                        string( ...
                        app.makeSafeTextForFileName( ...
                        payload.ScanName));
                    
                else
                    
                    error( ...
                        'The scan has no ScanID or ScanName.');
                end
            end
            
            scanID = ...
                app.makeSafeTextForFileName( ...
                payload.ScanID);
            
            payload.ScanID = ...
                string(scanID);
            
            % =============================================================
            % WORKSPACE
            % =============================================================
            
            workspacePath = ...
                fullfile( ...
                app.ScanWorkspaceRoot, ...
                scanID);
            
            sourceFolder = ...
                fullfile( ...
                workspacePath, ...
                'source');
            
            cosmicFolder = ...
                fullfile( ...
                workspacePath, ...
                '01_cosmic');
            
            artifactFolder = ...
                fullfile( ...
                workspacePath, ...
                '02_artifact');
            
            backgroundFolder = ...
                fullfile( ...
                workspacePath, ...
                '03_background');
            
            singleExtractFolder = ...
                fullfile( ...
                workspacePath, ...
                '04_single_extract');
            
            logsFolder = ...
                fullfile( ...
                workspacePath, ...
                'logs');
            
            manifestPath = ...
                fullfile( ...
                workspacePath, ...
                'manifest.mat');
            
            sourceInfoPath = ...
                fullfile( ...
                sourceFolder, ...
                'source_info.mat');
            
            % =============================================================
            % CREATE ONLY STRUCTURAL FOLDERS
            % =============================================================
            
            folders = { ...
                workspacePath, ...
                sourceFolder, ...
                cosmicFolder, ...
                artifactFolder, ...
                backgroundFolder, ...
                singleExtractFolder, ...
                logsFolder};
            
            for i = 1:numel(folders)
                
                if ~isfolder(folders{i})
                    mkdir(folders{i});
                end
            end
            
            % =============================================================
            % SOURCE INFO
            %
            % NO CUBE IS COPIED HERE.
            % =============================================================
            
            SourceInfo = struct();
            
            SourceInfo.SchemaVersion = ...
                2;
            
            SourceInfo.ScanID = ...
                string(scanID);
            
            if isfield(payload, 'ScanName')
                
                SourceInfo.ScanName = ...
                    string(payload.ScanName);
                
            else
                
                SourceInfo.ScanName = ...
                    string(scanID);
            end
            
            if isfield(payload, 'SourceMatPath')
                
                SourceInfo.SourceMatPath = ...
                    string(payload.SourceMatPath);
                
            else
                
                SourceInfo.SourceMatPath = ...
                    "";
            end
            
            if isfield(payload, 'SourceFolder')
                
                SourceInfo.SourceFolder = ...
                    string(payload.SourceFolder);
                
            else
                
                SourceInfo.SourceFolder = ...
                    "";
            end
            
            if isfield(payload, 'TracePath')
                
                SourceInfo.TracePath = ...
                    string(payload.TracePath);
                
            elseif isfield(payload, 'TraceImagePath')
                
                SourceInfo.TracePath = ...
                    string(payload.TraceImagePath);
                
            else
                
                SourceInfo.TracePath = ...
                    "";
            end
            
            SourceInfo.WorkspacePath = ...
                string(workspacePath);
            
            % Lightweight file identity.
            SourceInfo.SourceFileBytes = ...
                NaN;
            
            SourceInfo.SourceFileModified = ...
                "";
            
            sourcePathChar = ...
                char(SourceInfo.SourceMatPath);
            
            if ~isempty(sourcePathChar) && ...
                    isfile(sourcePathChar)
                
                d = dir(sourcePathChar);
                
                SourceInfo.SourceFileBytes = ...
                    d.bytes;
                
                SourceInfo.SourceFileModified = ...
                    string(d.date);
            end
            
            SourceInfo.LastUpdated = ...
                datetime('now');
            
            save( ...
                sourceInfoPath, ...
                'SourceInfo', ...
                '-v7');
            
            % =============================================================
            % LOAD MANIFEST
            % =============================================================
            
            Manifest = struct();
            
            if isfile(manifestPath)
                
                try
                    
                    T = ...
                        load( ...
                        manifestPath, ...
                        'Manifest');
                    
                    if isfield(T, 'Manifest') && ...
                            isstruct(T.Manifest)
                        
                        Manifest = ...
                            T.Manifest;
                    end
                    
                catch ME
                    
                    warning( ...
                        'RamanCube:ManifestLoadFailed', ...
                        '%s', ...
                        ME.message);
                end
            end
            
            % =============================================================
            % GENERAL MANIFEST
            % =============================================================
            
            Manifest.SchemaVersion = ...
                2;
            
            Manifest.StoragePolicy = ...
                ['READABLE=one canonical cube; ' ...
                'Cosmic=one materialized corrected cube; ' ...
                'Artifact/Background=linked checkpoints without cube duplication'];
            
            Manifest.ScanID = ...
                string(scanID);
            
            if isfield(payload, 'ScanName')
                
                Manifest.ScanName = ...
                    string(payload.ScanName);
                
            else
                
                Manifest.ScanName = ...
                    string(scanID);
            end
            
            Manifest.WorkspacePath = ...
                string(workspacePath);
            
            Manifest.Source = struct();
            
            Manifest.Source.MatPath = ...
                SourceInfo.SourceMatPath;
            
            Manifest.Source.Folder = ...
                SourceInfo.SourceFolder;
            
            Manifest.Source.TracePath = ...
                SourceInfo.TracePath;
            
            Manifest.Source.SourceInfoPath = ...
                string(sourceInfoPath);
            
            Manifest.Source.FileBytes = ...
                SourceInfo.SourceFileBytes;
            
            Manifest.Source.FileModified = ...
                SourceInfo.SourceFileModified;
            
            % =============================================================
            % STAGES
            % =============================================================
            
            if ~isfield(Manifest, 'Stages') || ...
                    ~isstruct(Manifest.Stages)
                
                Manifest.Stages = struct();
            end
            
            Manifest.Stages.Original = struct( ...
                'Available', true, ...
                'Completed', true, ...
                'Stale', false, ...
                'CheckpointPath', SourceInfo.SourceMatPath, ...
                'StatePath', SourceInfo.SourceMatPath, ...
                'EvidenceFolder', "", ...
                'StorageMode', "materialized_canonical_cube");
            
            stageNames = { ...
                'Cosmic', ...
                'Artifact', ...
                'Background', ...
                'SingleExtract'};
            
            for i = 1:numel(stageNames)
                
                stageName = ...
                    stageNames{i};
                
                if ~isfield(Manifest.Stages, stageName)
                    
                    Manifest.Stages.(stageName) = ...
                        struct( ...
                        'Available', false, ...
                        'Completed', false, ...
                        'Stale', false, ...
                        'CheckpointPath', "", ...
                        'StatePath', "", ...
                        'EvidenceFolder', "", ...
                        'StorageMode', "", ...
                        'CompletedAt', "");
                end
            end
            
            if ~isfield(Manifest, 'LastCompletedStage') || ...
                    strlength(string(Manifest.LastCompletedStage)) == 0
                
                Manifest.LastCompletedStage = ...
                    "Original";
            end
            
            Manifest.LastOpenedStage = ...
                "Original";
            
            Manifest.LastModified = ...
                datetime('now');
            
            save( ...
                manifestPath, ...
                'Manifest', ...
                '-v7');
            
            % =============================================================
            % ATTACH PATHS TO PAYLOAD
            % =============================================================
            
            payload.ScanWorkspaceRoot = ...
                string(app.ScanWorkspaceRoot);
            
            payload.WorkspacePath = ...
                string(workspacePath);
            
            payload.ManifestPath = ...
                string(manifestPath);
            
            payload.SourceInfoPath = ...
                string(sourceInfoPath);
            
            payload.CosmicWorkspacePath = ...
                string(cosmicFolder);
            
            payload.ArtifactWorkspacePath = ...
                string(artifactFolder);
            
            payload.BackgroundWorkspacePath = ...
                string(backgroundFolder);
            
            payload.SingleExtractWorkspacePath = ...
                string(singleExtractFolder);
            
            % =============================================================
            % HISTORY
            % =============================================================
            
            app.addHistory(sprintf( ...
                'Scan workspace ready: %s', ...
                workspacePath));
            
        end


        %% ============================================================
        %  SCAN ANALYSIS: MULTISCALE CHEMICAL ORGANIZATION ENTROPY
        %% ============================================================
        
        function pretreatPath = resolveScanAnalysisInputFile(app)
            
            pretreatPath = '';
            
            % 1) Prefer the pretreat result returned from scanpretreat.
            if ~isempty(app.PretreatResult) && isstruct(app.PretreatResult) && ...
                    isfield(app.PretreatResult,'SavedMatPath') && ...
                    exist(app.PretreatResult.SavedMatPath,'file')
                
                pretreatPath = char(app.PretreatResult.SavedMatPath);
                return
            end
            
            % 2) Otherwise, find the latest artifact/cosmic pretreat file.
            app.ensureScanHomeFolders();
            
            candidateFolders = { ...
                fullfile(app.ScanOutputRoot,'pretreat','02_artifact','data'), ...
                fullfile(app.ScanOutputRoot,'pretreat','01_cosmic','data'), ...
                fullfile(app.ScanOutputRoot,'analysis_ready')};
            
            allFiles = [];
            
            for i = 1:numel(candidateFolders)
                f = candidateFolders{i};
                if exist(f,'dir')
                    d = dir(fullfile(f,'*.mat'));
                    for k = 1:numel(d)
                        d(k).folder = f; %#ok<AGROW>
                    end
                    allFiles = [allFiles; d(:)]; %#ok<AGROW>
                end
            end
            
            if ~isempty(allFiles)
                [~,idx] = max([allFiles.datenum]);
                latestPath = fullfile(allFiles(idx).folder,allFiles(idx).name);
                
                choice = uiconfirm(app.UIFigure, ...
                    {'No active pretreat payload was found.'; ...
                    ''; ...
                    'Use the latest saved pretreat MAT file?'; ...
                    ''; ...
                    latestPath}, ...
                    'Select scan analysis input', ...
                    'Options',{'Use latest','Choose file','Cancel'}, ...
                    'DefaultOption','Use latest', ...
                    'CancelOption','Cancel');
                
                if strcmp(choice,'Use latest')
                    pretreatPath = latestPath;
                    return
                elseif strcmp(choice,'Cancel')
                    return
                end
            end
            
            % 3) Manual file selection.
            startFolder = fullfile(app.ScanOutputRoot,'pretreat','02_artifact','data');
            if ~exist(startFolder,'dir')
                startFolder = app.ScanOutputRoot;
            end
            
            [fileName,pathName] = uigetfile({'*.mat','MAT files (*.mat)'}, ...
                'Select pretreated scan MAT file',startFolder);
            
            if isequal(fileName,0)
                pretreatPath = '';
            else
                pretreatPath = fullfile(pathName,fileName);
            end
        end
        
        
        function result = runScanChemicalOrganizationAnalysis(app,pretreatPath)
            
            app.ensureScanHomeFolders();
            
            data = app.loadPretreatedScanForAnalysis(pretreatPath);
            
            cube = double(data.Cube);
            wn = double(data.Wavenumber(:));
            validMask = logical(data.ValidMask);
            
            if ndims(cube) ~= 3
                error('Input Raman cube must be a 3-D array: Y x X x Wavenumber.');
            end
            
            [ny,nx,nWn] = size(cube);
            
            if numel(wn) ~= nWn
                error('Wavenumber length does not match cube spectral dimension.');
            end
            
            [analysisMask,totalSignalMap,maskInfo] = app.buildScanAnalysisMask(cube,wn,validMask);
            
            if nnz(analysisMask) < 20
                error('Too few analysis pixels after valid/artifact/background masking.');
            end
            
            % ===== Load user-selected Raman band definitions =====
            bandDefPath = app.resolveScanBandDefinitionFile();
            
            if isempty(bandDefPath)
                error('No component band definition file was selected. Please run the band-selection script first.');
            end
            
            BandTable = app.loadSelectedBandDefinitionTable(bandDefPath);
            
            app.addHistory(['Band definition file: ' bandDefPath]);
            
            % ===== Baseline-correct cube for chemical component maps =====
            % This is used only for component/state/entropy analysis.
            % Mask/QC still comes from the pretreated cube.
            useBaselineCorrectedForComponents = false;
            
            if useBaselineCorrectedForComponents
                cubeChem = app.baselineCorrectCubeForComponentAnalysis(cube,analysisMask);
            else
                cubeChem = cube;
            end
            
            [componentMaps,componentZMaps,componentNames,componentLabels,componentInfo,totalControlMap] = ...
                app.buildChemicalComponentMapsFromBandTable(cubeChem,wn,analysisMask,BandTable);
            
            if numel(componentNames) < 2
                error('Fewer than 2 biochemical component maps were available. Check the wavenumber range.');
            end
            
            [stateMap,stateNames,stateInfo] = app.assignChemicalStatesAnalysis( ...
                componentZMaps,componentLabels,analysisMask);
            
            entropyScales = [3 7 15 31];
            entropyScales = entropyScales(entropyScales <= max(ny,nx));
            
            if isempty(entropyScales)
                entropyScales = 3;
            end
            
            shuffleN = 20;
            
            [entropyScaleTable,entropyMaps,shuffleEntropyMaps,globalStateEntropy] = ...
                app.computeMultiscaleStateEntropyAnalysis( ...
                stateMap,analysisMask,numel(stateNames),entropyScales,shuffleN);
            
            stateFractionTable = app.computeStateFractionTableAnalysis( ...
                stateMap,analysisMask,stateNames);
            
            scanID = app.makeSafeTextForFileName(data.ScanID);
            scanName = data.ScanName;
            
            outRoot = fullfile(app.ScanOutputRoot,'analysis_ready','scan_analysis');
            outFolder = fullfile(outRoot,scanID);
            
            if ~exist(outFolder,'dir')
                mkdir(outFolder);
            end
            
            figFolder = fullfile(outFolder,'figures');
            if ~exist(figFolder,'dir')
                mkdir(figFolder);
            end
            
            summaryTable = app.buildScanAnalysisSummaryTable( ...
                data,pretreatPath,outFolder,analysisMask,validMask,maskInfo, ...
                globalStateEntropy,entropyScaleTable,stateInfo);
            
            componentInfoTable = struct2table(componentInfo);
            
            writetable(summaryTable,fullfile(outFolder,[scanID '_summary.csv']));
            writetable(componentInfoTable,fullfile(outFolder,[scanID '_component_definitions.csv']));
            writetable(stateFractionTable,fullfile(outFolder,[scanID '_state_fractions.csv']));
            writetable(entropyScaleTable,fullfile(outFolder,[scanID '_multiscale_entropy.csv']));
            
            matPath = fullfile(outFolder,[scanID '_scan_chemical_organization.mat']);
            
            save(matPath, ...
                'data', ...
                'pretreatPath', ...
                'analysisMask', ...
                'validMask', ...
                'totalSignalMap', ...
                'maskInfo', ...
                'componentMaps', ...
                'componentZMaps', ...
                'componentNames', ...
                'componentLabels', ...
                'componentInfo', ...
                'stateMap', ...
                'stateNames', ...
                'stateInfo', ...
                'stateFractionTable', ...
                'entropyScales', ...
                'entropyMaps', ...
                'shuffleEntropyMaps', ...
                'entropyScaleTable', ...
                'globalStateEntropy', ...
                'summaryTable', ...
                'bandDefPath', ...
                'BandTable', ...
                'totalControlMap', ...
                '-v7.3');
            
            app.saveScanAnalysisFigures( ...
                figFolder,scanID,scanName,analysisMask,totalSignalMap, ...
                componentZMaps,componentLabels,stateMap,stateNames, ...
                entropyMaps,shuffleEntropyMaps,entropyScaleTable);
            
            app.appendScanAnalysisMasterLog(summaryTable);
            
            result = struct();
            result.ScanName = scanName;
            result.ScanID = scanID;
            result.OutputFolder = outFolder;
            result.MatPath = matPath;
            result.AnalysisPixels = nnz(analysisMask);
            result.ValidPixels = nnz(validMask);
            result.GlobalStateEntropy = globalStateEntropy;
            result.MeanMultiscaleEntropy = mean(entropyScaleTable.MeanLocalEntropy,'omitnan');
            result.MeanSpatialOrganizationIndex = mean(entropyScaleTable.SpatialOrganizationIndex,'omitnan');
        end
        
        
        function data = loadPretreatedScanForAnalysis(app,pretreatPath)
            
            S = load(pretreatPath);
            
            data = struct();
            data.InputPath = pretreatPath;
            data.InputStage = 'Unknown';
            
            if isfield(S,'RamanDataArtifact')
                R = S.RamanDataArtifact;
                data.InputStage = 'Artifact';
            elseif isfield(S,'RamanDataCosmic')
                R = S.RamanDataCosmic;
                data.InputStage = 'Cosmic';
            elseif isfield(S,'RamanData')
                R = S.RamanData;
                data.InputStage = 'RawRamanData';
            else
                R = app.findRamanStructInLoadedMat(S);
                data.InputStage = 'DetectedRamanStruct';
            end
            
            if ~isfield(R,'Cube')
                error('No Raman cube was found in the selected MAT file.');
            end
            
            data.Cube = double(R.Cube);
            
            if isfield(R,'Wavenumber')
                data.Wavenumber = double(R.Wavenumber(:));
            else
                data.Wavenumber = (1:size(data.Cube,3)).';
            end
            
            [ny,nx,~] = size(data.Cube);
            
            validMask = true(ny,nx);
            
            if isfield(S,'ValidPixelMask2D') && isequal(size(S.ValidPixelMask2D),[ny nx])
                validMask = logical(S.ValidPixelMask2D);
            elseif isfield(S,'ROIMask') && isequal(size(S.ROIMask),[ny nx])
                validMask = logical(S.ROIMask);
            end
            
            finiteMask = all(isfinite(data.Cube),3);
            data.ValidMask = validMask & finiteMask;
            
            data.ScanName = '';
            data.ScanID = '';
            
            if isfield(S,'ProcessingInfo')
                if isfield(S.ProcessingInfo,'ScanName')
                    data.ScanName = char(S.ProcessingInfo.ScanName);
                end
                if isfield(S.ProcessingInfo,'ScanID')
                    data.ScanID = char(S.ProcessingInfo.ScanID);
                end
            end
            
            if isempty(data.ScanName) && isfield(S,'PretreatState') && isfield(S.PretreatState,'ScanName')
                data.ScanName = char(S.PretreatState.ScanName);
            end
            
            if isempty(data.ScanID) && isfield(S,'PretreatState') && isfield(S.PretreatState,'ScanID')
                data.ScanID = char(S.PretreatState.ScanID);
            end
            
            if isempty(data.ScanName)
                [~,data.ScanName] = fileparts(pretreatPath);
            end
            
            if isempty(data.ScanID)
                data.ScanID = app.makeSafeTextForFileName(data.ScanName);
            end
            
            data.LoadedDate = datestr(now,'yyyy-mm-dd HH:MM:SS');
        end
        
        
        function R = findRamanStructInLoadedMat(app,S)
            
            names = fieldnames(S);
            
            for i = 1:numel(names)
                v = S.(names{i});
                if isstruct(v) && isfield(v,'Cube')
                    R = v;
                    return
                end
            end
            
            error('No struct with field Cube was found in MAT file.');
        end
        
        
        function [analysisMask,totalSignalMap,maskInfo] = buildScanAnalysisMask(app,cube,wn,validMask)
            
            [totalSignalMap,ok] = app.buildBandMapForRangesAnalysis( ...
                cube,wn,[600 1800; 2800 3050]);
            
            if ~ok
                totalSignalMap = mean(cube,3,'omitnan');
            end
            
            totalSignalMap = app.cleanMapForAnalysis(totalSignalMap);
            
            validMask = logical(validMask) & isfinite(totalSignalMap);
            vals = double(totalSignalMap(validMask));
            vals = vals(isfinite(vals));
            
            if isempty(vals)
                analysisMask = false(size(validMask));
                maskInfo = struct('Method','empty valid mask','Threshold',NaN, ...
                    'ValidPixels',0,'AnalysisPixels',0);
                return
            end
            
            p05 = prctile(vals,5);
            p15 = prctile(vals,15);
            p25 = prctile(vals,25);
            
            otsuNorm = app.otsuThresholdNormalizedAnalysis(vals);
            
            vmin = min(vals);
            vmax = max(vals);
            
            if isfinite(otsuNorm) && vmax > vmin
                otsuThr = vmin + otsuNorm*(vmax-vmin);
            else
                otsuThr = p15;
            end
            
            % Conservative auto-foreground rule:
            % remove low Raman-signal/background pixels, but do not over-segment.
            threshold = max(p15,min(otsuThr,p25));
            
            analysisMask = validMask & totalSignalMap >= threshold;
            
            if nnz(analysisMask) < max(20,round(0.03*nnz(validMask)))
                threshold = p05;
                analysisMask = validMask & totalSignalMap >= threshold;
            end
            
            maskInfo = struct();
            maskInfo.Method = 'ValidPixelMask2D/ROI plus conservative auto Raman-signal foreground';
            maskInfo.Threshold = threshold;
            maskInfo.OtsuThreshold = otsuThr;
            maskInfo.Percentile05 = p05;
            maskInfo.Percentile15 = p15;
            maskInfo.Percentile25 = p25;
            maskInfo.ValidPixels = nnz(validMask);
            maskInfo.AnalysisPixels = nnz(analysisMask);
            maskInfo.AnalysisFractionOfValid = nnz(analysisMask)/max(nnz(validMask),1);
        end
        
        
        function [componentMaps,componentZMaps,componentNames,componentLabels,componentInfo] = ...
                buildChemicalComponentMapsAnalysis(app,cube,wn,analysisMask)
            
            defs = struct('Name',{},'Label',{},'Ranges',{});
            
            defs(1).Name = 'LipidRich_CH';
            defs(1).Label = 'lipid-rich CH state';
            defs(1).Ranges = [2840 2940; 1440 1475];
            
            defs(2).Name = 'ProteinRich';
            defs(2).Label = 'protein-rich Raman state';
            defs(2).Ranges = [1000 1010; 1600 1700; 1230 1300];
            
            defs(3).Name = 'NucleicAcidRich';
            defs(3).Label = 'nucleic-acid-rich Raman state';
            defs(3).Ranges = [770 810; 1080 1125; 1330 1385];
            
            defs(4).Name = 'CHDeformation';
            defs(4).Label = 'CH2/CH3 deformation state';
            defs(4).Ranges = [1420 1485];
            
            componentMaps = zeros(size(cube,1),size(cube,2),0);
            componentZMaps = zeros(size(cube,1),size(cube,2),0);
            componentNames = {};
            componentLabels = {};
            componentInfo = struct('Name',{},'Label',{},'Ranges',{}, ...
                'AvailablePoints',{},'MedianInMask',{},'RobustSigmaInMask',{});
            
            for i = 1:numel(defs)
                
                [mp,ok,nPoints,rangeText] = app.buildBandMapForRangesAnalysis( ...
                    cube,wn,defs(i).Ranges);
                
                if ~ok
                    continue
                end
                
                mp = app.cleanMapForAnalysis(mp);
                vals = mp(analysisMask);
                vals = vals(isfinite(vals));
                
                if numel(vals) < 10
                    continue
                end
                
                medVal = median(vals,'omitnan');
                sigVal = app.robustSigmaVectorAnalysis(vals);
                
                z = (mp - medVal) ./ max(sigVal,eps);
                z(~isfinite(z)) = 0;
                z(z < 0) = 0;
                
                componentMaps(:,:,end+1) = mp; %#ok<AGROW>
                componentZMaps(:,:,end+1) = z; %#ok<AGROW>
                componentNames{end+1,1} = defs(i).Name; %#ok<AGROW>
                componentLabels{end+1,1} = defs(i).Label; %#ok<AGROW>
                
                j = numel(componentNames);
                componentInfo(j).Name = defs(i).Name;
                componentInfo(j).Label = defs(i).Label;
                componentInfo(j).Ranges = rangeText;
                componentInfo(j).AvailablePoints = nPoints;
                componentInfo(j).MedianInMask = medVal;
                componentInfo(j).RobustSigmaInMask = sigVal;
            end
        end
        
        
        function [mapImg,ok,nPoints,rangeText] = buildBandMapForRangesAnalysis(app,cube,wn,ranges)
            
            wn = double(wn(:));
            useIdx = false(numel(wn),1);
            parts = cell(size(ranges,1),1);
            
            for r = 1:size(ranges,1)
                lo = ranges(r,1);
                hi = ranges(r,2);
                if lo > hi
                    tmp = lo;
                    lo = hi;
                    hi = tmp;
                end
                useIdx = useIdx | (wn >= lo & wn <= hi);
                parts{r} = sprintf('%.0f-%.0f',lo,hi);
            end
            
            nPoints = nnz(useIdx);
            rangeText = strjoin(parts,'; ');
            
            if nPoints < 2
                mapImg = [];
                ok = false;
                return
            end
            
            mapImg = mean(cube(:,:,useIdx),3,'omitnan');
            mapImg = double(mapImg);
            ok = true;
        end
        
        
        function img = cleanMapForAnalysis(app,img)
            
            img = double(img);
            vals = img(isfinite(img));
            
            if isempty(vals)
                img = zeros(size(img));
                return
            end
            
            fillVal = median(vals,'omitnan');
            img(~isfinite(img)) = fillVal;
        end
        
        
        function [stateMap,stateNames,stateInfo] = assignChemicalStatesAnalysis(app, ...
                componentZMaps,componentLabels,analysisMask)
            
            [ny,nx,nComp] = size(componentZMaps);
            
            [maxZ,maxIdx] = max(componentZMaps,[],3);
            
            if nComp >= 2
                sortedZ = sort(componentZMaps,3,'descend');
                secondZ = sortedZ(:,:,2);
            else
                secondZ = zeros(ny,nx);
            end
            
            stateMap = zeros(ny,nx);
            
            dominant = analysisMask & maxZ >= 0.75 & (maxZ - secondZ) >= 0.25;
            ambiguous = analysisMask & ~dominant;
            
            stateMap(dominant) = maxIdx(dominant);
            mixedState = nComp + 1;
            stateMap(ambiguous) = mixedState;
            
            stateNames = componentLabels(:);
            stateNames{mixedState,1} = 'mixed / ambiguous biochemical state';
            
            stateInfo = struct();
            stateInfo.Rule = 'Dominant state if max robust-z >= 0.75 and exceeds second-best by >= 0.25; otherwise mixed/ambiguous.';
            stateInfo.DominantThreshold = 0.75;
            stateInfo.MarginThreshold = 0.25;
            stateInfo.MixedStateIndex = mixedState;
            stateInfo.NumberOfStates = numel(stateNames);
        end
        
        

        function [entropyScaleTable,entropyMaps,shuffleEntropyMaps,globalStateEntropy] = ...
                computeMultiscaleStateEntropyAnalysis(app,stateMap,analysisMask,nStates,scales,shuffleN)
            
            stateVals = stateMap(analysisMask);
            stateVals = stateVals(stateVals >= 1 & stateVals <= nStates);
            
            if isempty(stateVals)
                error('No chemical states were assigned inside analysis mask.');
            end
            
            counts = histcounts(stateVals,0.5:1:(nStates+0.5));
            p = counts ./ max(sum(counts),1);
            p = p(p > 0);
            globalStateEntropy = -sum(p .* log(p)) / log(nStates);
            
            entropyMaps = cell(numel(scales),1);
            shuffleEntropyMaps = cell(numel(scales),1);
            
            ScalePx = zeros(numel(scales),1);
            MeanLocalEntropy = zeros(numel(scales),1);
            ShuffleMeanEntropy = zeros(numel(scales),1);
            SpatialOrganizationIndex = zeros(numel(scales),1);
            AnalysisPixels = zeros(numel(scales),1);
            ShuffleN = zeros(numel(scales),1);
            
            for s = 1:numel(scales)
                
                win = scales(s);
                
                Hmap = app.localStateEntropyMapAnalysis( ...
                    stateMap,analysisMask,nStates,win);
                
                entropyMaps{s} = Hmap;
                
                safeMask = isfinite(Hmap) & analysisMask;
                MeanLocalEntropy(s) = mean(Hmap(safeMask),'omitnan');
                
                shuffleMeans = zeros(shuffleN,1);
                shuffleMapAccum = zeros(size(stateMap));
                
                for b = 1:shuffleN
                    
                    shuffled = zeros(size(stateMap));
                    permVals = stateVals(randperm(numel(stateVals)));
                    idxMask = find(analysisMask);
                    shuffled(idxMask(1:numel(permVals))) = permVals;
                    
                    Hsh = app.localStateEntropyMapAnalysis( ...
                        shuffled,analysisMask,nStates,win);
                    
                    shuffleMeans(b) = mean(Hsh(analysisMask),'omitnan');
                    shuffleMapAccum = shuffleMapAccum + Hsh;
                end
                
                HshuffleMeanMap = shuffleMapAccum ./ max(shuffleN,1);
                shuffleEntropyMaps{s} = HshuffleMeanMap;
                
                ShuffleMeanEntropy(s) = mean(shuffleMeans,'omitnan');
                SpatialOrganizationIndex(s) = ...
                    (ShuffleMeanEntropy(s) - MeanLocalEntropy(s)) ./ max(ShuffleMeanEntropy(s),eps);
                
                ScalePx(s) = win;
                AnalysisPixels(s) = nnz(safeMask);
                ShuffleN(s) = shuffleN;
            end
            
            entropyScaleTable = table( ...
                ScalePx, ...
                MeanLocalEntropy, ...
                ShuffleMeanEntropy, ...
                SpatialOrganizationIndex, ...
                AnalysisPixels, ...
                ShuffleN);
        end
        
        
        function Hmap = localStateEntropyMapAnalysis(app,stateMap,analysisMask,nStates,win)
            
            win = max(3,round(win));
            
            if mod(win,2) == 0
                win = win + 1;
            end
            
            kernel = ones(win,win);
            validStateMask = analysisMask & stateMap >= 1 & stateMap <= nStates;
            totalCount = conv2(double(validStateMask),kernel,'same');
            
            Hmap = zeros(size(stateMap));
            
            for c = 1:nStates
                cCount = conv2(double(validStateMask & stateMap == c),kernel,'same');
                p = cCount ./ max(totalCount,eps);
                term = zeros(size(p));
                good = p > 0;
                term(good) = -p(good).*log(p(good));
                Hmap = Hmap + term;
            end
            
            Hmap = Hmap ./ log(nStates);
            
            % Artifact/boundary-aware safe mask:
            % only keep entropy pixels whose local window contains enough valid analysis pixels.
            minCount = min(win*win, max(3, round(0.60 * win * win)));
            safeMask = analysisMask & totalCount >= minCount;
            
            Hmap(~safeMask) = NaN;
        end
        
        
        function stateFractionTable = computeStateFractionTableAnalysis(app,stateMap,analysisMask,stateNames)
            
            nStates = numel(stateNames);
            StateIndex = (1:nStates)';
            StateName = string(stateNames(:));
            PixelCount = zeros(nStates,1);
            FractionOfAnalysisMask = zeros(nStates,1);
            
            denom = max(nnz(analysisMask),1);
            
            for c = 1:nStates
                PixelCount(c) = nnz(analysisMask & stateMap == c);
                FractionOfAnalysisMask(c) = PixelCount(c) / denom;
            end
            
            stateFractionTable = table( ...
                StateIndex, ...
                StateName, ...
                PixelCount, ...
                FractionOfAnalysisMask);
        end
        
        
        function summaryTable = buildScanAnalysisSummaryTable(app,data,pretreatPath,outFolder, ...
                analysisMask,validMask,maskInfo,globalStateEntropy,entropyScaleTable,stateInfo)
            
            SessionID = string(app.SessionID);
            User = string(app.SessionUserName);
            Role = string(app.SessionRole);
            Email = string(app.SessionEmail);
            DateTime = string(datestr(now,'yyyy-mm-dd HH:MM:SS'));
            ScanName = string(data.ScanName);
            ScanID = string(data.ScanID);
            InputStage = string(data.InputStage);
            InputFile = string(pretreatPath);
            OutputFolder = string(outFolder);
            ValidPixels = nnz(validMask);
            AnalysisPixels = nnz(analysisMask);
            AnalysisFractionOfValid = AnalysisPixels / max(ValidPixels,1);
            MaskMethod = string(maskInfo.Method);
            MaskThreshold = maskInfo.Threshold;
            GlobalStateEntropy = globalStateEntropy;
            MeanMultiscaleEntropy = mean(entropyScaleTable.MeanLocalEntropy,'omitnan');
            MeanSpatialOrganizationIndex = mean(entropyScaleTable.SpatialOrganizationIndex,'omitnan');
            StateRule = string(stateInfo.Rule);
            
            summaryTable = table( ...
                DateTime, ...
                SessionID, ...
                User, ...
                Role, ...
                Email, ...
                ScanName, ...
                ScanID, ...
                InputStage, ...
                InputFile, ...
                OutputFolder, ...
                ValidPixels, ...
                AnalysisPixels, ...
                AnalysisFractionOfValid, ...
                MaskMethod, ...
                MaskThreshold, ...
                GlobalStateEntropy, ...
                MeanMultiscaleEntropy, ...
                MeanSpatialOrganizationIndex, ...
                StateRule);
        end
        
        
        function appendScanAnalysisMasterLog(app,summaryTable)
            
            outRoot = fullfile(app.ScanOutputRoot,'metadata');
            
            if ~exist(outRoot,'dir')
                mkdir(outRoot);
            end
            
            logPath = fullfile(outRoot,'scan_analysis_master_log.csv');
            
            if exist(logPath,'file')
                try
                    old = readtable(logPath);
                    allT = [old; summaryTable];
                    writetable(allT,logPath);
                catch
                    writetable(summaryTable,logPath);
                end
            else
                writetable(summaryTable,logPath);
            end
        end
        
        
        function saveScanAnalysisFigures(app,figFolder,scanID,scanName,analysisMask,totalSignalMap, ...
                componentZMaps,componentLabels,stateMap,stateNames,entropyMaps,shuffleEntropyMaps,entropyScaleTable)
            
            if ~exist(figFolder,'dir')
                mkdir(figFolder);
            end
            
            app.saveScanAnalysisMaskFigure(figFolder,scanID,scanName,totalSignalMap,analysisMask);
            app.saveScanAnalysisComponentFigure(figFolder,scanID,scanName,componentZMaps,componentLabels,analysisMask);
            app.saveScanAnalysisStateFigure(figFolder,scanID,scanName,stateMap,stateNames,analysisMask);
            app.saveScanAnalysisEntropyFigure(figFolder,scanID,scanName,entropyMaps,analysisMask,entropyScaleTable);
            app.saveScanAnalysisEntropySpectrumFigure(figFolder,scanID,scanName,entropyScaleTable);
            
            % Optional null-control maps: saved only for first scale to keep output compact.
            if ~isempty(shuffleEntropyMaps)
                fig = figure('Visible','off','Color','w','Position',[100 100 900 750]);
                ax = axes(fig);
                img = shuffleEntropyMaps{1};
                imagesc(ax,img);
                axis(ax,'image');
                ax.YDir = 'normal';
                colormap(ax,'turbo');
                colorbar(ax);
                title(ax,{scanName,'Mean shuffled entropy map', ...
                    sprintf('Scale = %d px',entropyScaleTable.ScalePx(1))}, ...
                    'Interpreter','none');
                exportgraphics(fig,fullfile(figFolder,[scanID '_shuffle_entropy_map_scale_' ...
                    num2str(entropyScaleTable.ScalePx(1)) '.png']),'Resolution',300);
                close(fig);
            end
        end
        
        
        function saveScanAnalysisMaskFigure(app,figFolder,scanID,scanName,totalSignalMap,analysisMask)
            
            fig = figure('Visible','off','Color','w','Position',[100 100 1250 620]);
            tiledlayout(fig,1,2,'Padding','compact','TileSpacing','compact');
            
            ax1 = nexttile;
            imagesc(ax1,totalSignalMap);
            axis(ax1,'image');
            ax1.YDir = 'normal';
            colormap(ax1,'turbo');
            colorbar(ax1);
            title(ax1,{scanName,'Total Raman signal map'},'Interpreter','none');
            app.applyRobustImageCLimAnalysis(ax1,totalSignalMap,[]);
            
            ax2 = nexttile;
            imagesc(ax2,analysisMask);
            axis(ax2,'image');
            ax2.YDir = 'normal';
            colormap(ax2,'gray');
            colorbar(ax2);
            title(ax2,sprintf('Analysis mask | pixels = %d',nnz(analysisMask)),'Interpreter','none');
            
            exportgraphics(fig,fullfile(figFolder,[scanID '_analysis_mask.png']),'Resolution',300);
            close(fig);
        end
        
        
        function saveScanAnalysisComponentFigure(app,figFolder,scanID,scanName,componentZMaps,componentLabels,analysisMask)
            
            nComp = size(componentZMaps,3);
            nCol = min(2,nComp);
            nRow = ceil(nComp/nCol);
            
            fig = figure('Visible','off','Color','w','Position',[100 100 620*nCol 520*nRow]);
            tiledlayout(fig,nRow,nCol,'Padding','compact','TileSpacing','compact');
            
            for i = 1:nComp
                ax = nexttile;
                img = componentZMaps(:,:,i);
                img(~analysisMask) = NaN;
                imagesc(ax,img);
                axis(ax,'image');
                ax.YDir = 'normal';
                colormap(ax,'turbo');
                colorbar(ax);
                title(ax,componentLabels{i},'Interpreter','none');
                app.applyRobustImageCLimAnalysis(ax,img,analysisMask);
            end
            
            sgtitle(fig,{scanName,'Robust-z biochemical component maps'},'Interpreter','none');
            exportgraphics(fig,fullfile(figFolder,[scanID '_component_z_maps.png']),'Resolution',300);
            close(fig);
        end
        
        
        function saveScanAnalysisStateFigure(app,figFolder,scanID,scanName,stateMap,stateNames,analysisMask)
            
            plotState = stateMap;
            plotState(~analysisMask) = 0;
            
            fig = figure('Visible','off','Color','w','Position',[100 100 900 760]);
            ax = axes(fig);
            imagesc(ax,plotState);
            axis(ax,'image');
            ax.YDir = 'normal';
            colormap(ax,parula(numel(stateNames)+1));
            cb = colorbar(ax);
            cb.Ticks = 0:numel(stateNames);
            tickLabels = [{'outside analysis mask'}; cellstr(string(stateNames(:)))];
            cb.TickLabels = tickLabels;
            title(ax,{scanName,'Raman-defined biochemical state map'},'Interpreter','none');
            exportgraphics(fig,fullfile(figFolder,[scanID '_chemical_state_map.png']),'Resolution',300);
            close(fig);
        end
        
        
        function saveScanAnalysisEntropyFigure(app,figFolder,scanID,scanName,entropyMaps,analysisMask,entropyScaleTable)
            
            nS = numel(entropyMaps);
            nCol = min(2,nS);
            nRow = ceil(nS/nCol);
            
            fig = figure('Visible','off','Color','w','Position',[100 100 620*nCol 520*nRow]);
            tiledlayout(fig,nRow,nCol,'Padding','compact','TileSpacing','compact');
            
            for i = 1:nS
                ax = nexttile;
                img = entropyMaps{i};
                img(~analysisMask) = NaN;
                imagesc(ax,img);
                axis(ax,'image');
                ax.YDir = 'normal';
                colormap(ax,'turbo');
                colorbar(ax);
                caxis(ax,[0 1]);
                title(ax,sprintf('Local state entropy | scale %d px | mean %.3f', ...
                    entropyScaleTable.ScalePx(i),entropyScaleTable.MeanLocalEntropy(i)), ...
                    'Interpreter','none');
            end
            
            sgtitle(fig,{scanName,'Multiscale chemical organization entropy'},'Interpreter','none');
            exportgraphics(fig,fullfile(figFolder,[scanID '_multiscale_entropy_maps.png']),'Resolution',300);
            close(fig);
        end
        
        
        function saveScanAnalysisEntropySpectrumFigure(app,figFolder,scanID,scanName,entropyScaleTable)
            
            fig = figure('Visible','off','Color','w','Position',[100 100 900 620]);
            ax = axes(fig);
            
            plot(ax,entropyScaleTable.ScalePx,entropyScaleTable.MeanLocalEntropy,'-o','LineWidth',1.6);
            hold(ax,'on');
            plot(ax,entropyScaleTable.ScalePx,entropyScaleTable.ShuffleMeanEntropy,'--o','LineWidth',1.6);
            hold(ax,'off');
            
            xlabel(ax,'Neighborhood scale, pixels');
            ylabel(ax,'Normalized entropy');
            ylim(ax,[0 1]);
            title(ax,{scanName,'Entropy spectrum: real map vs shuffled spatial null'},'Interpreter','none');
            legend(ax,{'Real chemical-state map','Shuffled null'},'Location','best');
            box(ax,'on');
            
            exportgraphics(fig,fullfile(figFolder,[scanID '_entropy_spectrum.png']),'Resolution',300);
            close(fig);
        end
        
        
        function applyRobustImageCLimAnalysis(app,ax,img,mask)
            
            if nargin < 4 || isempty(mask)
                vals = img(isfinite(img));
            else
                vals = img(mask & isfinite(img));
            end
            
            if isempty(vals)
                return
            end
            
            lo = prctile(vals,2);
            hi = prctile(vals,98);
            
            if isfinite(lo) && isfinite(hi) && lo < hi
                caxis(ax,[lo hi]);
            end
        end
        
        
        function sig = robustSigmaVectorAnalysis(app,v)
            
            v = double(v(:));
            v = v(isfinite(v));
            
            if isempty(v)
                sig = 1;
                return
            end
            
            sig = 1.4826 * mad(v,1);
            
            if sig <= eps || isnan(sig)
                sig = std(v,'omitnan');
            end
            
            if sig <= eps || isnan(sig)
                sig = 1;
            end
        end
        
        
        function t = otsuThresholdNormalizedAnalysis(app,vals)
            
            vals = double(vals(:));
            vals = vals(isfinite(vals));
            
            if numel(vals) < 10 || max(vals) <= min(vals)
                t = NaN;
                return
            end
            
            v = (vals - min(vals)) ./ (max(vals)-min(vals));
            edges = linspace(0,1,257);
            counts = histcounts(v,edges);
            p = counts ./ max(sum(counts),1);
            centers = (edges(1:end-1)+edges(2:end))/2;
            
            omega = cumsum(p);
            mu = cumsum(p .* centers);
            muT = mu(end);
            
            sigmaB = (muT .* omega - mu).^2 ./ max(omega .* (1-omega),eps);
            sigmaB(~isfinite(sigmaB)) = 0;
            
            [~,idx] = max(sigmaB);
            t = centers(idx);
        end
        
        
        function bandDefPath = resolveScanBandDefinitionFile(app)
            
            bandDefPath = '';
            
            rootFolder = fullfile(app.ScanOutputRoot,'metadata','band_definitions');
            
            if ~exist(rootFolder,'dir')
                uialert(app.UIFigure, ...
                    {'No band definition folder was found.'; ...
                    ''; ...
                    'Please run select_component_bands_from_pretreated_scan.m first.'}, ...
                    'Band definition not found');
                return
            end
            
            % Prefer MAT files because they preserve BandTable and metadata.
            matFiles = dir(fullfile(rootFolder,'**','*_component_band_definitions.mat'));
            csvFiles = dir(fullfile(rootFolder,'**','*_component_band_definitions.csv'));
            txtFiles = dir(fullfile(rootFolder,'**','*_component_band_definitions.txt'));
            
            allFiles = [matFiles(:); csvFiles(:); txtFiles(:)];
            
            if isempty(allFiles)
                uialert(app.UIFigure, ...
                    {'No component band definition file was found.'; ...
                    ''; ...
                    'Expected files:'; ...
                    '*_component_band_definitions.mat'; ...
                    '*_component_band_definitions.csv'; ...
                    '*_component_band_definitions.txt'}, ...
                    'Band definition not found');
                return
            end
            
            [~,idx] = max([allFiles.datenum]);
            latestPath = fullfile(allFiles(idx).folder,allFiles(idx).name);
            
            choice = uiconfirm(app.UIFigure, ...
                { ...
                'Use latest component band definition file?'; ...
                ''; ...
                latestPath}, ...
                'Component band definition', ...
                'Options',{'Use latest','Choose file','Cancel'}, ...
                'DefaultOption','Use latest', ...
                'CancelOption','Cancel');
            
            if strcmp(choice,'Use latest')
                bandDefPath = latestPath;
                return
            elseif strcmp(choice,'Cancel')
                return
            end
            
            [fileName,pathName] = uigetfile( ...
                {'*.mat;*.csv;*.txt','Band definition files (*.mat, *.csv, *.txt)'}, ...
                'Select component band definition file', ...
                rootFolder);
            
            if isequal(fileName,0)
                bandDefPath = '';
            else
                bandDefPath = fullfile(pathName,fileName);
            end
        end
        
        
        function BandTable = loadSelectedBandDefinitionTable(app,bandDefPath)
            
            [~,~,ext] = fileparts(bandDefPath);
            
            switch lower(ext)
                
                case '.mat'
                    S = load(bandDefPath);
                    
                    if ~isfield(S,'BandTable')
                        error('Selected MAT file does not contain BandTable.');
                    end
                    
                    BandTable = S.BandTable;
                    
                case '.csv'
                    BandTable = readtable(bandDefPath);
                    
                case '.txt'
                    BandTable = readtable(bandDefPath, ...
                        'FileType','text', ...
                        'Delimiter','\t');
                    
                otherwise
                    error('Unsupported band definition file type: %s', ext);
            end
            
            requiredVars = {'Component','BandName','SelectedLow','SelectedHigh','Decision'};
            
            for i = 1:numel(requiredVars)
                if ~ismember(requiredVars{i},BandTable.Properties.VariableNames)
                    error('Band definition table is missing required column: %s', requiredVars{i});
                end
            end
            
            % Convert to consistent types.
            BandTable.Component = string(BandTable.Component);
            BandTable.BandName = string(BandTable.BandName);
            BandTable.Decision = string(BandTable.Decision);
            
            if ismember('UseFor',BandTable.Properties.VariableNames)
                BandTable.UseFor = string(BandTable.UseFor);
            else
                BandTable.UseFor = strings(height(BandTable),1);
            end
            
            % Keep only selected usable bands.
            good = ...
                isfinite(BandTable.SelectedLow) & ...
                isfinite(BandTable.SelectedHigh) & ...
                BandTable.SelectedHigh > BandTable.SelectedLow & ...
                ~contains(lower(BandTable.Decision),'skip');
            
            BandTable = BandTable(good,:);
            
            if height(BandTable) < 1
                error('No usable selected bands were found in the band definition table.');
            end
        end
        
        
        function cubeCorrected = baselineCorrectCubeForComponentAnalysis(app,cube,analysisMask)
            
            if exist('function_baseline_peakstripping','file') ~= 2
                warning('function_baseline_peakstripping.m was not found. Using non-baseline-corrected cube.');
                cubeCorrected = cube;
                return
            end
            
            cube = double(cube);
            [ny,nx,nWn] = size(cube);
            
            cubeCorrected = cube;
            
            pix = find(analysisMask(:));
            
            if isempty(pix)
                return
            end
            
            h = waitbar(0, ...
                'Baseline correcting spectra for Scan analysis...', ...
                'Name','Scan analysis baseline correction');
            
            cleaner = onCleanup(@() closeWaitbarSafely(h));
            
            X = reshape(cube,ny*nx,nWn);
            Xc = X;
            
            for ii = 1:numel(pix)
                
                p = pix(ii);
                spec = double(X(p,:)).';
                
                if all(isfinite(spec))
                    try
                        [~,corrSpec] = function_baseline_peakstripping(spec);
                        corrSpec = double(corrSpec(:));
                        corrSpec(~isfinite(corrSpec)) = 0;
                        Xc(p,:) = corrSpec(:).';
                    catch
                        % Keep original spectrum if baseline correction fails.
                        Xc(p,:) = X(p,:);
                    end
                end
                
                if mod(ii,250) == 0 || ii == numel(pix)
                    try
                        waitbar(ii/numel(pix), h, ...
                            sprintf('Baseline correcting spectra: %d / %d', ii, numel(pix)));
                    catch
                    end
                end
            end
            
            cubeCorrected = reshape(Xc,ny,nx,nWn);
            
            function closeWaitbarSafely(hh)
                try
                    if isvalid(hh)
                        close(hh);
                    end
                catch
                end
            end
        end
        
        
        function [componentMaps,componentZMaps,componentNames,componentLabels,componentInfo,totalControlMap] = ...
                buildChemicalComponentMapsFromBandTable(app,cube,wn,analysisMask,BandTable)
            
            cube = double(cube);
            wn = double(wn(:));
            
            % ===== Build normalization / control map =====
            totalControlMap = [];
            
            qcRows = BandTable(contains(BandTable.Component,'BiomassQC','IgnoreCase',true),:);
            
            if ~isempty(qcRows)
                % Prefer fingerprint total if available.
                fpRows = qcRows(contains(qcRows.BandName,'Fingerprint','IgnoreCase',true),:);
                
                if isempty(fpRows)
                    fpRows = qcRows(1,:);
                end
                
                totalControlMap = app.buildComponentRawMapFromRows(cube,wn,fpRows);
            end
            
            if isempty(totalControlMap)
                [totalControlMap,ok] = app.buildBandMapForRangesAnalysis(cube,wn,[600 1800]);
                
                if ~ok
                    totalControlMap = mean(cube,3,'omitnan');
                end
            end
            
            totalControlMap = app.cleanMapForAnalysis(totalControlMap);
            
            valsTotal = totalControlMap(analysisMask);
            valsTotal = valsTotal(isfinite(valsTotal));
            
            if isempty(valsTotal)
                totalScale = 1;
            else
                totalScale = median(valsTotal,'omitnan');
            end
            
            totalControlMapNorm = totalControlMap ./ max(totalScale,eps);
            totalControlMapNorm(totalControlMapNorm <= eps) = eps;
            
            % ===== Component rows =====
            allComponents = unique(BandTable.Component,'stable');
            
            skipComponents = contains(allComponents,'BiomassQC','IgnoreCase',true);
            
            componentList = allComponents(~skipComponents);
            
            if isempty(componentList)
                error('No non-QC chemical components were found in BandTable.');
            end
            
            componentMaps = zeros(size(cube,1),size(cube,2),0);
            componentZMaps = zeros(size(cube,1),size(cube,2),0);
            componentNames = {};
            componentLabels = {};
            componentInfo = struct('Name',{},'Label',{},'Ranges',{}, ...
                'AvailablePoints',{},'MedianInMask',{},'RobustSigmaInMask',{}, ...
                'Normalization',{});
            
            for i = 1:numel(componentList)
                
                compName = componentList(i);
                rows = BandTable(BandTable.Component == compName,:);
                
                if isempty(rows)
                    continue
                end
                
                rawMap = app.buildComponentRawMapFromRows(cube,wn,rows);
                rawMap = app.cleanMapForAnalysis(rawMap);
                
                % Normalize by total Raman control map to reduce thickness/biomass effect.
                indexMap = rawMap ./ totalControlMapNorm;
                indexMap = app.cleanMapForAnalysis(indexMap);
                
                vals = indexMap(analysisMask);
                vals = vals(isfinite(vals));
                
                if numel(vals) < 10
                    continue
                end
                
                medVal = median(vals,'omitnan');
                sigVal = app.robustSigmaVectorAnalysis(vals);
                
                z = (indexMap - medVal) ./ max(sigVal,eps);
                z(~isfinite(z)) = 0;
                z(z < 0) = 0;
                
                componentMaps(:,:,end+1) = indexMap; %#ok<AGROW>
                componentZMaps(:,:,end+1) = z; %#ok<AGROW>
                
                safeName = app.makeSafeTextForFileName(compName);
                componentNames{end+1,1} = safeName; %#ok<AGROW>
                labelText = char(compName);
                labelText = strrep(labelText,'LipidRich_CH','lipid-rich Raman component');
                labelText = strrep(labelText,'ProteinRich','protein-rich Raman component');
                labelText = strrep(labelText,'NucleicAcidAssociated','nucleic-acid-associated Raman component');
                labelText = strrep(labelText,'StressCytochromeAssociated','stress/cytochrome-associated Raman component');
                
                componentLabels{end+1,1} = labelText;
                
                rangeText = app.makeRangeTextFromBandRows(rows);
                nPoints = app.countAvailablePointsFromBandRows(wn,rows);
                
                j = numel(componentNames);
                componentInfo(j).Name = safeName;
                componentInfo(j).Label = char(compName);
                componentInfo(j).Ranges = rangeText;
                componentInfo(j).AvailablePoints = nPoints;
                componentInfo(j).MedianInMask = medVal;
                componentInfo(j).RobustSigmaInMask = sigVal;
                componentInfo(j).Normalization = 'component selected-band map / BiomassQC fingerprint-total map';
            end
            
            if size(componentMaps,3) < 2
                error('Fewer than 2 component maps were built from selected BandTable. Check selected bands.');
            end
        end
        
        
        function compMap = buildComponentRawMapFromRows(app,cube,wn,rows)
            
            maps = zeros(size(cube,1),size(cube,2),0);
            
            for r = 1:height(rows)
                
                lo = rows.SelectedLow(r);
                hi = rows.SelectedHigh(r);
                
                if ~isfinite(lo) || ~isfinite(hi) || hi <= lo
                    continue
                end
                
                [mp,ok] = app.buildBandMapForRangesAnalysis(cube,wn,[lo hi]);
                
                if ok
                    maps(:,:,end+1) = mp; %#ok<AGROW>
                end
            end
            
            if isempty(maps)
                compMap = zeros(size(cube,1),size(cube,2));
            else
                compMap = mean(maps,3,'omitnan');
            end
        end
        
        function rangeText = makeRangeTextFromBandRows(app,rows)
            
            parts = strings(height(rows),1);
            
            for r = 1:height(rows)
                parts(r) = sprintf('%s: %.1f-%.1f', ...
                    string(rows.BandName(r)), ...
                    rows.SelectedLow(r), ...
                    rows.SelectedHigh(r));
            end
            
            rangeText = char(strjoin(parts,'; '));
        end
        
        
        function nPoints = countAvailablePointsFromBandRows(app,wn,rows)
            
            wn = double(wn(:));
            useIdx = false(numel(wn),1);
            
            for r = 1:height(rows)
                lo = rows.SelectedLow(r);
                hi = rows.SelectedHigh(r);
                
                if lo > hi
                    tmp = lo;
                    lo = hi;
                    hi = tmp;
                end
                
                useIdx = useIdx | (wn >= lo & wn <= hi);
            end
            
            nPoints = nnz(useIdx);
        end


        function value = readSessionTextField(app, S, fieldName, defaultValue)
            value = defaultValue;
            if ~isstruct(S)
                return
            end
            if ~isfield(S, fieldName)
                return
            end
            rawValue = S.(fieldName);
            if isempty(rawValue)
                return
            end
            if isstring(rawValue)
                value = char(rawValue(1));
            elseif ischar(rawValue)
                value = rawValue;
            elseif isnumeric(rawValue)
                value = num2str(rawValue);
            else
                try
                    value = char(rawValue);
                catch
                    value = defaultValue;
                end
            end
        end


        function logToHome(app, actionName, detailText)
            
            if nargin < 3
                detailText = '';
            end
            
            try
                
                if isempty(app.mainHomeApp) || ...
                        ~isvalid(app.mainHomeApp)
                    
                    return
                end
                
                if ismethod(app.mainHomeApp, 'logSessionEvent')
                    
                    app.mainHomeApp.logSessionEvent( ...
                        'Raman scan', ...
                        actionName, ...
                        detailText);
                    
                end
                
            catch
                % Child app history must continue even if Home audit is unavailable.
            end
        end
        
        
        function setScanControlsEnabled(app, enabled)
            
            if enabled
                state = 'on';
            else
                state = 'off';
            end
            
            app.LoadRamanscanButton.Enable = state;
            
            app.ScanpretreatButton.Enable = state;
            app.SinglespectraextractionButton.Enable = state;
            app.SinglespectraanalysisButton.Enable = state;
            app.ScananalysisButton.Enable = state;
            app.FinishButton.Enable = state;
        end
        
        
        function [payload, ok] = buildSingleExtractionPayload(app)
            
            % =============================================================
            % BUILD PAYLOAD FOR single_extract
            %
            % The payload must preserve:
            %   Cube       = committed pretreated cube
            %   RawCube    = original raw cube
            %   ValidMask  = pixels allowed for segmentation
            %   ArtifactMask2D = excluded artifact pixels
            % =============================================================
            
            payload = ...
                struct();
            
            ok = ...
                false;
            
            % =============================================================
            % 1. REQUIRE ACTIVE SCAN
            % =============================================================
            
            if isempty(app.ActiveLoadedScan) || ...
                    ~isstruct(app.ActiveLoadedScan)
                
                uialert( ...
                    app.UIFigure, ...
                    sprintf([ ...
                    'No active pretreated Raman scan is available.\n\n' ...
                    'Please run Scan pre-treat and finish at least the ' ...
                    'Cosmic or Artifact stage first.']), ...
                    'No active pretreat scan');
                
                return
            end
            
            source = ...
                app.ActiveLoadedScan;
            
            requiredFields = { ...
                'ScanName', ...
                'ScanID', ...
                'Cube', ...
                'RawCube', ...
                'Wavenumber', ...
                'ValidMask'};
            
            missingFields = {};
            
            for k = 1:numel(requiredFields)
                
                fieldName = ...
                    requiredFields{k};
                
                if ~isfield(source, fieldName) || ...
                        isempty(source.(fieldName))
                    
                    missingFields{end + 1} = ...
                        fieldName; %#ok<AGROW>
                end
            end
            
            if ~isempty(missingFields)
                
                uialert( ...
                    app.UIFigure, ...
                    sprintf( ...
                    ['ActiveLoadedScan is missing: %s\n\n' ...
                    'RawCube must be transferred from scanpretreat before ' ...
                    'single_extract can export raw and pretreated spectra.'], ...
                    strjoin(missingFields, ', ')), ...
                    'Incomplete active scan');
                
                return
            end
            
            % =============================================================
            % 2. VALIDATE PRETREATED CUBE
            % =============================================================
            
            cube = ...
                double(source.Cube);
            
            if isempty(cube) || ...
                    ndims(cube) ~= 3
                
                uialert( ...
                    app.UIFigure, ...
                    'ActiveLoadedScan.Cube must be a non-empty 3-D cube.', ...
                    'Invalid pretreated cube');
                
                return
            end
            
            [ny, nx, nWn] = ...
                size(cube);
            
            % =============================================================
            % 3. VALIDATE RAW CUBE
            % =============================================================
            
            rawCube = ...
                double(source.RawCube);
            
            if isempty(rawCube) || ...
                    ndims(rawCube) ~= 3
                
                uialert( ...
                    app.UIFigure, ...
                    'ActiveLoadedScan.RawCube must be a non-empty 3-D cube.', ...
                    'Invalid raw cube');
                
                return
            end
            
            if ~isequal(size(rawCube), size(cube))
                
                uialert( ...
                    app.UIFigure, ...
                    { ...
                    'RawCube and Cube dimensions do not match.'; ...
                    ['RawCube: ' mat2str(size(rawCube))]; ...
                    ['Cube: ' mat2str(size(cube))]}, ...
                    'Cube-size mismatch');
                
                return
            end
            
            % =============================================================
            % 4. VALIDATE WAVENUMBER
            % =============================================================
            
            wn = ...
                double(source.Wavenumber(:));
            
            if numel(wn) ~= nWn
                
                uialert( ...
                    app.UIFigure, ...
                    ['ActiveLoadedScan.Wavenumber does not match the ' ...
                    'cube spectral dimension.'], ...
                    'Wavenumber mismatch');
                
                return
            end
            
            % =============================================================
            % 5. ARTIFACT MASK
            % =============================================================
            
            if isfield(source, 'ArtifactMask2D') && ...
                    isequal(size(source.ArtifactMask2D), [ny nx])
                
                artifactMask = ...
                    logical(source.ArtifactMask2D);
                
            else
                
                artifactMask = ...
                    false(ny, nx);
            end
            
            % =============================================================
            % 6. VALID MASK
            % =============================================================
            
            finitePretreatedMask = ...
                all(isfinite(cube), 3);
            
            if isequal(size(source.ValidMask), [ny nx])
                
                validMask = ...
                    logical(source.ValidMask) & ...
                    finitePretreatedMask & ...
                    ~artifactMask;
                
            else
                
                validMask = ...
                    finitePretreatedMask & ...
                    ~artifactMask;
            end
            
            if ~any(validMask(:))
                
                uialert( ...
                    app.UIFigure, ...
                    'No valid pixel remains for cluster segmentation.', ...
                    'Empty extraction mask');
                
                return
            end
            
            % =============================================================
            % 7. CHECK RAW DATA AT VALID PIXELS
            %
            % RawCube may contain non-finite values outside the analysis mask,
            % but every valid cluster candidate pixel must have a complete raw
            % spectrum.
            % =============================================================
            
            finiteRawMask = ...
                all(isfinite(rawCube), 3);
            
            invalidRawInsideValid = ...
                validMask & ...
                ~finiteRawMask;
            
            if any(invalidRawInsideValid(:))
                
                uialert( ...
                    app.UIFigure, ...
                    sprintf([ ...
                    'RawCube contains incomplete spectra inside the valid mask.\n\n' ...
                    'Affected pixels: %d\n\n' ...
                    'These pixels must be removed from ValidMask or corrected ' ...
                    'before cluster export.'], ...
                    nnz(invalidRawInsideValid)), ...
                    'Invalid raw spectra');
                
                return
            end
            
            % =============================================================
            % 8. COPY COMPLETE SOURCE PAYLOAD
            %
            % This preserves cosmic, artifact, session, workspace and stage
            % metadata already received from scanpretreat.
            % =============================================================
            
            payload = ...
                source;
            
            % =============================================================
            % 9. NORMALIZE CORE FIELDS
            % =============================================================
            
            payload.Cube = ...
                cube;
            
            payload.RawCube = ...
                rawCube;
            
            payload.Wavenumber = ...
                wn;
            
            payload.ValidMask = ...
                logical(validMask);
            
            payload.ValidMask2D = ...
                logical(validMask);
            
            payload.ArtifactMask2D = ...
                logical(artifactMask);
            
            payload.ScanName = ...
                string(source.ScanName);
            
            payload.ScanID = ...
                string(source.ScanID);
            
            % =============================================================
            % 10. SOURCE PATHS
            % =============================================================
            
            if ~isfield(payload, 'SourceMatPath')
                
                payload.SourceMatPath = ...
                    "";
                
            else
                
                payload.SourceMatPath = ...
                    string(payload.SourceMatPath);
            end
            
            if ~isfield(payload, 'OriginalSourceMatPath')
                
                if isfield(app.OriginalLoadedScan, 'SourceMatPath')
                    
                    payload.OriginalSourceMatPath = ...
                        string(app.OriginalLoadedScan.SourceMatPath);
                    
                else
                    
                    payload.OriginalSourceMatPath = ...
                        "";
                end
                
            else
                
                payload.OriginalSourceMatPath = ...
                    string(payload.OriginalSourceMatPath);
            end
            
            payload.OutputRoot = ...
                string(app.ScanOutputRoot);
            
            % =============================================================
            % 11. DATA DEFINITIONS
            % =============================================================
            
            payload.RawCubeDefinition = ...
                ['Original Raman acquisition cube before cosmic correction ' ...
                'and artifact masking.'];
            
            payload.PretreatedCubeDefinition = ...
                ['Committed Raman cube used for PCA/K-means segmentation. ' ...
                'Cosmic correction is included when committed.'];
            
            payload.ArtifactHandling = ...
                ['Artifact pixels are excluded using ValidMask2D and ' ...
                'ArtifactMask2D. Display-only inpainting is not included ' ...
                'as analytical spectral data.'];
            
            payload.RawAndPretreatedPixelAlignment = ...
                ['RawCube(y,x,k) and Cube(y,x,k) refer to the same ' ...
                'spatial acquisition pixel and wavenumber channel.'];
            
            payload.CubeDimensionOrder = ...
                'row x column x wavenumber';
            
            payload.ClusterExportSpectraOrientation = ...
                'rows = pixels; columns = wavenumber channels';
            
            % =============================================================
            % 12. SOURCE AND ACTION
            % =============================================================
            
            payload.SourceApp = ...
                'scanspectra';
            
            payload.SourceAction = ...
                'Single spectra extraction';
            
            payload.TransferTarget = ...
                'single_extract.receiveSegmentationPayload';
            
            % =============================================================
            % 13. PAYLOAD SUMMARY
            % =============================================================
            
            payload.CubeSize = ...
                [ny nx nWn];
            
            payload.RawCubeSize = ...
                size(rawCube);
            
            payload.ValidPixelCount = ...
                nnz(validMask);
            
            payload.ArtifactPixelCount = ...
                nnz(artifactMask);
            
            payload.TotalPixelCount = ...
                numel(validMask);
            
            payload.RawCubeIncluded = ...
                true;
            
            payload.PretreatedCubeIncluded = ...
                true;
            
            payload.DisplayInpaintingTransferred = ...
                false;
            
            payload.PreparedForSingleExtractionAt = ...
                datestr(now, 'yyyy-mm-dd HH:MM:SS');
            
            payload.PreparedForSingleExtractionAtDateTime = ...
                datetime('now');
            
            payload.MATLABVersion = ...
                version;
            
            ok = ...
                true;
            
        end
        
        
        function payload = buildUnmixingPayload(app)
            
            % =============================================================
            % BUILD INPUT FOR RELATIVE RAMAN UNMIXING
            %
            % Priority:
            %   1. ActiveLoadedScan already containing a confirmed cell mask
            %   2. Latest Background MAT returned by scanpretreat
            % =============================================================
            
            payload = struct();
            
            % =============================================================
            % 1. TRY ACTIVE SCAN IN MEMORY
            % =============================================================
            if ~isempty(app.ActiveLoadedScan) && ...
                    isstruct(app.ActiveLoadedScan) && ...
                    isfield(app.ActiveLoadedScan, 'Cube') && ...
                    ~isempty(app.ActiveLoadedScan.Cube)
                
                candidate = app.ActiveLoadedScan;
                
                hasMask = ...
                    isfield(candidate, 'ValidMask') && ...
                    ~isempty(candidate.ValidMask);
                
                if hasMask
                    
                    cube = double(candidate.Cube);
                    [ny, nx, nWn] = size(cube);
                    
                    validMask = logical(candidate.ValidMask);
                    
                    if isequal(size(validMask), [ny nx]) && ...
                            any(validMask(:))
                        
                        payload = candidate;
                        
                        payload.Cube = cube;
                        payload.Wavenumber = ...
                            double(candidate.Wavenumber(:));
                        
                        % ValidMask in the unmixing app means:
                        % accepted cell pixels used for analysis.
                        payload.ValidMask = validMask;
                        payload.CellMask2D = validMask;
                        
                        if ~isfield(payload, 'BackgroundMask2D')
                            payload.BackgroundMask2D = ...
                                false(ny, nx);
                        end
                        
                        if ~isfield(payload, 'UncertainMask2D')
                            payload.UncertainMask2D = ...
                                false(ny, nx);
                        end
                        
                        if ~isfield(payload, 'ArtifactMask2D')
                            payload.ArtifactMask2D = ...
                                false(ny, nx);
                        end
                        
                        if ~isfield(payload, 'Stage')
                            payload.Stage = ...
                                "Background-confirmed cell scan";
                        end
                        
                        payload.SourceMode = ...
                            "ActiveLoadedScan";
                        
                        app.validateUnmixingPayload(payload);
                        return
                    end
                end
            end
            
            % =============================================================
            % 2. FIND BACKGROUND RESULT PATH FROM PRETREAT PAYLOAD
            % =============================================================
            backgroundPath = '';
            
            if ~isempty(app.PretreatResult) && ...
                    isstruct(app.PretreatResult) && ...
                    isfield(app.PretreatResult, 'SavedMatPath') && ...
                    ~isempty(app.PretreatResult.SavedMatPath)
                
                candidatePath = ...
                    char(app.PretreatResult.SavedMatPath);
                
                if exist(candidatePath, 'file') == 2
                    backgroundPath = candidatePath;
                end
            end
            
            % =============================================================
            % 3. FIND LATEST SAVED BACKGROUND FILE
            % =============================================================
            if isempty(backgroundPath)
                
                backgroundFolder = fullfile( ...
                    app.ScanOutputRoot, ...
                    'pretreat', ...
                    '03_background', ...
                    'data');
                
                if isfolder(backgroundFolder)
                    
                    files = dir(fullfile( ...
                        backgroundFolder, ...
                        '*.mat'));
                    
                    if ~isempty(files)
                        
                        [~, idx] = max([files.datenum]);
                        
                        latestPath = fullfile( ...
                            files(idx).folder, ...
                            files(idx).name);
                        
                        choice = uiconfirm( ...
                            app.UIFigure, ...
                            { ...
                            'No Background result is active in memory.'; ...
                            ''; ...
                            'Use the latest saved Background result?'; ...
                            ''; ...
                            latestPath}, ...
                            'Unmixing input', ...
                            'Options', { ...
                            'Use latest', ...
                            'Choose file', ...
                            'Cancel'}, ...
                            'DefaultOption', ...
                            'Use latest', ...
                            'CancelOption', ...
                            'Cancel');
                        
                        switch choice
                            
                            case 'Use latest'
                                backgroundPath = latestPath;
                                
                            case 'Choose file'
                                
                                [fileName, pathName] = ...
                                    uigetfile( ...
                                    {'*.mat', ...
                                    'Background MAT files (*.mat)'}, ...
                                    'Select Background result', ...
                                    backgroundFolder);
                                
                                if isequal(fileName, 0)
                                    error( ...
                                        'Unmixing input selection was cancelled.');
                                end
                                
                                backgroundPath = ...
                                    fullfile(pathName, fileName);
                                
                            otherwise
                                error( ...
                                    'Unmixing input selection was cancelled.');
                        end
                    end
                end
            end
            
            % =============================================================
            % 4. MANUAL FILE SELECTION IF NOTHING WAS FOUND
            % =============================================================
            if isempty(backgroundPath)
                
                startFolder = app.ScanOutputRoot;
                
                [fileName, pathName] = ...
                    uigetfile( ...
                    {'*.mat', ...
                    'Background MAT files (*.mat)'}, ...
                    'Select confirmed Background result', ...
                    startFolder);
                
                if isequal(fileName, 0)
                    error( ...
                        'No Background result was selected.');
                end
                
                backgroundPath = ...
                    fullfile(pathName, fileName);
            end
            
            % =============================================================
            % 5. LOAD BACKGROUND DATA
            % =============================================================
            S = load(backgroundPath);
            
            if ~isfield(S, 'RamanDataBackground')
                
                error([ ...
                    'The selected MAT file does not contain ', ...
                    'RamanDataBackground.' newline newline ...
                    'Please select the final file saved after ', ...
                    'Background removal.']);
            end
            
            R = S.RamanDataBackground;
            
            if ~isstruct(R) || ...
                    ~isfield(R, 'Cube') || ...
                    isempty(R.Cube)
                
                error( ...
                    'RamanDataBackground does not contain a valid Cube.');
            end
            
            cube = double(R.Cube);
            
            if ndims(cube) ~= 3
                error( ...
                    'The Background Raman cube must be a 3-D array.');
            end
            
            [ny, nx, nWn] = size(cube);
            
            % =============================================================
            % 6. WAVENUMBER
            % =============================================================
            if isfield(R, 'Wavenumber') && ...
                    ~isempty(R.Wavenumber)
                
                wn = double(R.Wavenumber(:));
                
            elseif isfield(S, 'Wavenumber') && ...
                    ~isempty(S.Wavenumber)
                
                wn = double(S.Wavenumber(:));
                
            else
                error( ...
                    'No Raman wavenumber axis was found.');
            end
            
            if numel(wn) ~= nWn
                error([ ...
                    'Wavenumber length does not match the ', ...
                    'spectral dimension of the cube.']);
            end
            
            % =============================================================
            % 7. CELL MASK
            % =============================================================
            if isfield(S, 'CellMask2D') && ...
                    isequal(size(S.CellMask2D), [ny nx])
                
                cellMask = logical(S.CellMask2D);
                
            elseif isfield(R, 'CellMask2D') && ...
                    isequal(size(R.CellMask2D), [ny nx])
                
                cellMask = logical(R.CellMask2D);
                
            else
                error([ ...
                    'The selected Background file has no valid CellMask2D.' ...
                    newline ...
                    'Finish and validate Background removal first.']);
            end
            
            % Artifact-valid pixels may further restrict the cell mask.
            if isfield(S, 'ValidPixelMask2D') && ...
                    isequal(size(S.ValidPixelMask2D), [ny nx])
                
                artifactValidMask = ...
                    logical(S.ValidPixelMask2D);
                
                cellMask = ...
                    cellMask & artifactValidMask;
                
            else
                artifactValidMask = ...
                    all(isfinite(cube), 3);
                
                cellMask = ...
                    cellMask & artifactValidMask;
            end
            
            cellMask = ...
                cellMask & all(isfinite(cube), 3);
            
            if ~any(cellMask(:))
                error( ...
                    'The final cell mask contains no usable Raman pixels.');
            end
            
            % =============================================================
            % 8. OPTIONAL MASKS
            % =============================================================
            backgroundMask = false(ny, nx);
            uncertainMask = false(ny, nx);
            artifactMask = false(ny, nx);
            
            if isfield(S, 'BackgroundMask2D') && ...
                    isequal(size(S.BackgroundMask2D), [ny nx])
                
                backgroundMask = ...
                    logical(S.BackgroundMask2D);
            end
            
            if isfield(S, 'UncertainMask2D') && ...
                    isequal(size(S.UncertainMask2D), [ny nx])
                
                uncertainMask = ...
                    logical(S.UncertainMask2D);
            end
            
            if isfield(S, 'ArtifactMask2D') && ...
                    isequal(size(S.ArtifactMask2D), [ny nx])
                
                artifactMask = ...
                    logical(S.ArtifactMask2D);
            end
            
            % =============================================================
            % 9. SCAN IDENTITY
            % =============================================================
            scanName = '';
            scanID = '';
            
            if isfield(S, 'ProcessingInfo') && ...
                    isstruct(S.ProcessingInfo)
                
                if isfield(S.ProcessingInfo, 'ScanName')
                    scanName = ...
                        char(S.ProcessingInfo.ScanName);
                end
                
                if isfield(S.ProcessingInfo, 'ScanID')
                    scanID = ...
                        char(S.ProcessingInfo.ScanID);
                end
            end
            
            if isempty(scanName)
                
                if isfield(R, 'ScanName') && ...
                        ~isempty(R.ScanName)
                    
                    scanName = char(R.ScanName);
                else
                    [~, scanName] = ...
                        fileparts(backgroundPath);
                end
            end
            
            if isempty(scanID)
                scanID = ...
                    app.makeSafeTextForFileName(scanName);
            end
            
            % =============================================================
            % 10. FINAL PAYLOAD
            % =============================================================
            payload.SourceApp = ...
                "scanspectra";
            
            payload.SourceMode = ...
                "Saved Background MAT";
            
            payload.Stage = ...
                "Background-confirmed cell scan";
            
            payload.ScanName = ...
                string(scanName);
            
            payload.ScanID = ...
                string(scanID);
            
            payload.Cube = cube;
            payload.Wavenumber = wn;
            
            % Critical:
            % only accepted cell pixels are used in unmixing.
            payload.ValidMask = cellMask;
            payload.CellMask2D = cellMask;
            
            payload.BackgroundMask2D = ...
                backgroundMask;
            
            payload.UncertainMask2D = ...
                uncertainMask;
            
            payload.ArtifactMask2D = ...
                artifactMask;
            
            payload.ArtifactValidMask2D = ...
                artifactValidMask;
            
            payload.SourceMatPath = ...
                string(backgroundPath);
            
            payload.MaskSource = ...
                "CellMask2D intersect ValidPixelMask2D";
            
            payload.CreatedDate = ...
                string(datestr( ...
                now, ...
                'yyyy-mm-dd HH:MM:SS'));
            
            app.validateUnmixingPayload(payload);
        end
        
        
        function validateUnmixingPayload(app, payload)
            
            % =============================================================
            % VALIDATE INPUT BEFORE OPENING UNMIXING APP
            % =============================================================
            
            requiredFields = { ...
                'ScanName', ...
                'ScanID', ...
                'Cube', ...
                'Wavenumber', ...
                'ValidMask'};
            
            for k = 1:numel(requiredFields)
                
                fieldName = requiredFields{k};
                
                if ~isfield(payload, fieldName)
                    error( ...
                        'Unmixing payload is missing field: %s', ...
                        fieldName);
                end
            end
            
            if isempty(payload.Cube) || ...
                    ndims(payload.Cube) ~= 3
                
                error( ...
                    'Unmixing requires a 3-D Raman cube.');
            end
            
            [ny, nx, nWn] = size(payload.Cube);
            
            if isempty(payload.Wavenumber) || ...
                    numel(payload.Wavenumber) ~= nWn
                
                error([ ...
                    'The unmixing wavenumber axis does not match ', ...
                    'the Raman cube.']);
            end
            
            if isempty(payload.ValidMask) || ...
                    ~isequal(size(payload.ValidMask), [ny nx])
                
                error([ ...
                    'The unmixing cell mask does not match ', ...
                    'the spatial scan size.']);
            end
            
            if ~any(payload.ValidMask(:))
                error( ...
                    'No accepted cell pixels are available for unmixing.');
            end
            
            if nnz(payload.ValidMask) < 10
                error( ...
                    'Too few accepted cell pixels are available for unmixing.');
            end
        end
        
        
        function tf = hasOriginalLoadedScan(app)
            
            % =============================================================
            % CHECK WHETHER A VALID ORIGINAL SCAN EXISTS IN MEMORY
            % =============================================================
            
            tf = false;
            
            if isempty(app.OriginalLoadedScan) || ...
                    ~isstruct(app.OriginalLoadedScan)
                
                return
            end
            
            if ~isfield(app.OriginalLoadedScan, 'Cube') || ...
                    isempty(app.OriginalLoadedScan.Cube) || ...
                    ndims(app.OriginalLoadedScan.Cube) ~= 3
                
                return
            end
            
            if ~isfield(app.OriginalLoadedScan, 'Wavenumber') || ...
                    isempty(app.OriginalLoadedScan.Wavenumber)
                
                return
            end
            
            tf = ...
                numel(app.OriginalLoadedScan.Wavenumber) == ...
                size(app.OriginalLoadedScan.Cube, 3);
        end
        
        
        function tf = hasAvailableOriginalMatFiles(app)
            
            % =============================================================
            % CHECK FOR VALID RAW SCAN FOLDERS
            %
            % Required folder structure:
            %
            %   ScanInputRoot
            %       Scan_A
            %           rawcube.mat
            %           trace.png
            %
            % A folder is accepted only when:
            %   1. It contains exactly two files.
            %   2. One file is rawcube.mat.
            %   3. One file is an image named trace.
            % =============================================================
            
            tf = false;
            
            if isempty(app.ScanInputRoot) || ...
                    ~isfolder(app.ScanInputRoot)
                
                return
            end
            
            validFolders = ...
                app.collectValidRawScanFolders();
            
            tf = ~isempty(validFolders);
        end


        function scanFolders = collectValidRawScanFolders(app)
            
            % =============================================================
            % FIND VALID RAW SCAN SUBFOLDERS
            %
            % Each valid folder must contain exactly:
            %   rawcube.mat
            %   trace.<supported image extension>
            %
            % Any folder containing more or fewer than two files is excluded.
            % =============================================================
            
            scanFolders = struct( ...
                'Name', {}, ...
                'FolderPath', {}, ...
                'RawCubePath', {}, ...
                'TracePath', {});
            
            if isempty(app.ScanInputRoot) || ...
                    ~isfolder(app.ScanInputRoot)
                
                return
            end
            
            folderList = dir(app.ScanInputRoot);
            
            validCount = 0;
            
            supportedImageExtensions = { ...
                '.png', ...
                '.jpg', ...
                '.jpeg', ...
                '.tif', ...
                '.tiff', ...
                '.bmp'};
            
            for k = 1:numel(folderList)
                
                item = folderList(k);
                
                % Only inspect actual subfolders.
                if ~item.isdir || ...
                        strcmp(item.name, '.') || ...
                        strcmp(item.name, '..')
                    
                    continue
                end
                
                folderPath = fullfile( ...
                    item.folder, ...
                    item.name);
                
                contents = dir(folderPath);
                
                % Keep files only.
                contents = contents(~[contents.isdir]);
                
                % Ignore hidden/system metadata files that commonly appear
                % without being part of the scan data.
                fileNames = {contents.name};
                
                ignoreMask = ...
                    startsWith(fileNames, '.') | ...
                    strcmpi(fileNames, 'Thumbs.db') | ...
                    strcmpi(fileNames, 'desktop.ini');
                
                contents = contents(~ignoreMask);
                
                % ---------------------------------------------------------
                % Strict rule:
                % exactly two usable files must be present.
                % ---------------------------------------------------------
                if numel(contents) ~= 2
                    continue
                end
                
                rawCubePath = '';
                tracePath = '';
                
                for j = 1:numel(contents)
                    
                    thisName = contents(j).name;
                    
                    [~, baseName, extension] = ...
                        fileparts(thisName);
                    
                    % -----------------------------------------------------
                    % Required MAT file: rawcube.mat
                    % Case-insensitive comparison.
                    % -----------------------------------------------------
                    if strcmpi(baseName, 'rawcube') && ...
                            strcmpi(extension, '.mat')
                        
                        rawCubePath = fullfile( ...
                            contents(j).folder, ...
                            contents(j).name);
                        
                        continue
                    end
                    
                    % -----------------------------------------------------
                    % Required image file: trace.<image extension>
                    % -----------------------------------------------------
                    isSupportedImage = ...
                        any(strcmpi( ...
                        extension, ...
                        supportedImageExtensions));
                    
                    if strcmpi(baseName, 'trace') && ...
                            isSupportedImage
                        
                        tracePath = fullfile( ...
                            contents(j).folder, ...
                            contents(j).name);
                    end
                end
                
                % Folder is valid only if both required files were found.
                if isempty(rawCubePath) || ...
                        isempty(tracePath)
                    
                    continue
                end
                
                validCount = validCount + 1;
                
                scanFolders(validCount).Name = ...
                    item.name;
                
                scanFolders(validCount).FolderPath = ...
                    folderPath;
                
                scanFolders(validCount).RawCubePath = ...
                    rawCubePath;
                
                scanFolders(validCount).TracePath = ...
                    tracePath;
            end
            
            % Sort names alphabetically for stable menu display.
            if ~isempty(scanFolders)
                
                names = string({scanFolders.Name});
                
                [~, order] = sort( ...
                    lower(names));
                
                scanFolders = scanFolders(order);
            end
        end
        
        
        function openLoadScanApp(app)
            
            % =============================================================
            % OPEN LOADSCAN TO PREPARE A NEW RAW RAMAN SCAN
            % =============================================================
            
            scanSession = app.buildScanHomeSessionInfo();
            
            if isempty(app.loadScanApp) || ...
                    ~isvalid(app.loadScanApp)
                
                app.loadScanApp = Loadscan();
                
                if ~ismethod(app.loadScanApp, ...
                        'receiveScanHomeSession')
                    
                    error([ ...
                        'Loadscan does not contain ', ...
                        'receiveScanHomeSession.']);
                end
                
                app.loadScanApp.receiveScanHomeSession( ...
                    scanSession, ...
                    app);
                
                app.addHistory( ...
                    'Opened Loadscan to prepare a new original Raman scan.');
                
            else
                
                app.loadScanApp.UIFigure.Visible = 'on';
                
                try
                    figure(app.loadScanApp.UIFigure);
                catch
                end
                
                app.addHistory( ...
                    'Focused existing Loadscan app.');
            end
        end
        
        
        function loadExistingOriginalScan(app)
            
            % =============================================================
            % SELECT AN EXISTING SCAN BY SUBFOLDER NAME
            %
            % Only valid folders returned by
            % collectValidRawScanFolders() are displayed.
            % =============================================================
            
            scanFolders = ...
                app.collectValidRawScanFolders();
            
            if isempty(scanFolders)
                
                uialert( ...
                    app.UIFigure, ...
                    sprintf([ ...
                    'No valid Raman scan folder was found.\n\n' ...
                    'Input folder:\n%s\n\n' ...
                    'Each scan folder must contain exactly two files:\n' ...
                    '1. rawcube.mat\n' ...
                    '2. trace image\n\n' ...
                    'Folders with additional files are excluded.'], ...
                    app.ScanInputRoot), ...
                    'No valid scan folder');
                
                app.addHistory( ...
                    'No valid raw scan folder was available.');
                
                return
            end
            
            % =============================================================
            % BUILD DISPLAY LIST
            % =============================================================
            
            displayNames = ...
                {scanFolders.Name};
            
            [selectedIndex, confirmed] = ...
                listdlg( ...
                'PromptString', { ...
                'Select a Raman scan folder:'; ...
                ''; ...
                ['Input root: ' app.ScanInputRoot]}, ...
                'SelectionMode', ...
                'single', ...
                'ListString', ...
                displayNames, ...
                'ListSize', ...
                [440 340], ...
                'Name', ...
                'Load existing Raman scan', ...
                'OKString', ...
                'Load selected scan', ...
                'CancelString', ...
                'Cancel');
            
            if ~confirmed || ...
                    isempty(selectedIndex)
                
                app.addHistory( ...
                    'Existing scan folder selection was cancelled.');
                
                return
            end
            
            selectedFolder = ...
                scanFolders(selectedIndex);
            
            selectedMatPath = ...
                selectedFolder.RawCubePath;
            
            % =============================================================
            % LOAD AND STANDARDIZE RAWCUBE
            % =============================================================
            
            payload = ...
                app.buildOriginalPayloadFromMatFile( ...
                selectedMatPath);
            
            % Folder name is the official scan name.
            payload.ScanName = ...
                string(selectedFolder.Name);
            
            payload.ScanID = ...
                string( ...
                app.makeSafeTextForFileName( ...
                selectedFolder.Name));
            
            payload.SourceFolder = ...
                string(selectedFolder.FolderPath);
            
            payload.SourceMatPath = ...
                string(selectedFolder.RawCubePath);
            
            payload.TraceImagePath = ...
                string(selectedFolder.TracePath);
            
            payload.SourceMode = ...
                "Existing raw scan folder";
            
            payload.SourceStage = ...
                "Original";
            
            payload.Stage = ...
                "Original prepared Raman scan";
            
            % Centralized validation and storage.
            app.receivePreparedScan(payload);
            
            % =============================================================
            % HISTORY
            % =============================================================
            
            app.addHistory(sprintf( ...
                'Original scan folder selected: %s', ...
                selectedFolder.Name));
            
            app.addHistory([ ...
                'Raw cube MAT: ' ...
                selectedFolder.RawCubePath]);
            
            app.addHistory([ ...
                'Trace image: ' ...
                selectedFolder.TracePath]);
            
            % =============================================================
            % SUCCESS MESSAGE
            % =============================================================
            
            uialert( ...
                app.UIFigure, ...
                sprintf([ ...
                'Original Raman scan loaded successfully.\n\n' ...
                'Scan folder: %s\n' ...
                'Cube size: %d x %d x %d\n' ...
                'Valid pixels: %d / %d\n\n' ...
                'Raw cube:\n%s\n\n' ...
                'Trace image:\n%s'], ...
                selectedFolder.Name, ...
                size(payload.Cube, 1), ...
                size(payload.Cube, 2), ...
                size(payload.Cube, 3), ...
                nnz(payload.ValidMask), ...
                numel(payload.ValidMask), ...
                selectedFolder.RawCubePath, ...
                selectedFolder.TracePath), ...
                'Raman scan loaded');
        end
        
        
        function payload = buildOriginalPayloadFromMatFile(app, matPath)
            
            % =============================================================
            % LOAD AND STANDARDIZE AN ORIGINAL / PREPARED RAMAN MAT FILE
            % =============================================================
            
            if exist(matPath, 'file') ~= 2
                error('The selected MAT file does not exist.');
            end
            
            S = load(matPath);
            
            % -------------------------------------------------------------
            % Prevent processed files from silently becoming original data.
            % -------------------------------------------------------------
            processedFields = { ...
                'RamanDataCosmic', ...
                'RamanDataArtifact', ...
                'RamanDataBackground'};
            
            hasProcessedField = false;
            
            for k = 1:numel(processedFields)
                if isfield(S, processedFields{k})
                    hasProcessedField = true;
                    break
                end
            end
            
            % -------------------------------------------------------------
            % Find original Raman structure.
            % Priority is explicit prepared/raw variable names.
            % -------------------------------------------------------------
            R = struct();
            sourceVariable = '';
            
            preferredFields = { ...
                'PreparedScan', ...
                'PreparedRamanScan', ...
                'RamanDataRaw', ...
                'RamanData', ...
                'ScanData'};
            
            for k = 1:numel(preferredFields)
                
                fieldName = preferredFields{k};
                
                if isfield(S, fieldName) && ...
                        isstruct(S.(fieldName)) && ...
                        isfield(S.(fieldName), 'Cube')
                    
                    R = S.(fieldName);
                    sourceVariable = fieldName;
                    break
                end
            end
            
            % Accept top-level Cube when present.
            if isempty(fieldnames(R)) && ...
                    isfield(S, 'Cube')
                
                R.Cube = S.Cube;
                
                if isfield(S, 'Wavenumber')
                    R.Wavenumber = S.Wavenumber;
                end
                
                if isfield(S, 'ValidMask')
                    R.ValidMask = S.ValidMask;
                end
                
                sourceVariable = 'top-level Cube';
            end
            
            % Search other structures containing Cube.
            if isempty(fieldnames(R))
                
                names = fieldnames(S);
                
                for k = 1:numel(names)
                    
                    value = S.(names{k});
                    
                    if isstruct(value) && ...
                            isfield(value, 'Cube') && ...
                            ~isempty(value.Cube)
                        
                        % Do not silently use a processed stage as original.
                        if any(strcmp(names{k}, processedFields))
                            continue
                        end
                        
                        R = value;
                        sourceVariable = names{k};
                        break
                    end
                end
            end
            
            if isempty(fieldnames(R))
                
                if hasProcessedField
                    error([ ...
                        'The selected file contains pretreated Raman data, ', ...
                        'but no original RamanData structure was found.' ...
                        newline newline ...
                        'Select the original/prepared scan MAT file instead.']);
                else
                    error([ ...
                        'No Raman structure containing field Cube was found ', ...
                        'in the selected MAT file.']);
                end
            end
            
            % =============================================================
            % CUBE
            % =============================================================
            cube = double(R.Cube);
            
            if isempty(cube) || ndims(cube) ~= 3
                error( ...
                    'The selected Raman data must contain a 3-D cube.');
            end
            
            [ny, nx, nWn] = size(cube);
            
            % =============================================================
            % WAVENUMBER
            % =============================================================
            if isfield(R, 'Wavenumber') && ...
                    ~isempty(R.Wavenumber)
                
                wn = double(R.Wavenumber(:));
                
            elseif isfield(S, 'Wavenumber') && ...
                    ~isempty(S.Wavenumber)
                
                wn = double(S.Wavenumber(:));
                
            else
                error([ ...
                    'No Wavenumber axis was found in the selected MAT file.']);
            end
            
            if numel(wn) ~= nWn
                error([ ...
                    'Wavenumber length does not match the cube ', ...
                    'spectral dimension.']);
            end
            
            % =============================================================
            % VALID MASK
            % =============================================================
            finiteMask = all(isfinite(cube), 3);
            
            if isfield(R, 'ValidMask') && ...
                    isequal(size(R.ValidMask), [ny nx])
                
                validMask = logical(R.ValidMask);
                
            elseif isfield(S, 'ValidMask') && ...
                    isequal(size(S.ValidMask), [ny nx])
                
                validMask = logical(S.ValidMask);
                
            elseif isfield(S, 'ValidPixelMask2D') && ...
                    isequal(size(S.ValidPixelMask2D), [ny nx])
                
                validMask = logical(S.ValidPixelMask2D);
                
            elseif isfield(S, 'ROIMask') && ...
                    isequal(size(S.ROIMask), [ny nx])
                
                validMask = logical(S.ROIMask);
                
            else
                validMask = true(ny, nx);
            end
            
            validMask = validMask & finiteMask;
            
            if ~any(validMask(:))
                error( ...
                    'The selected Raman scan contains no valid pixels.');
            end
            
            % =============================================================
            % SCAN NAME
            % =============================================================
            [~, fileBaseName] = fileparts(matPath);
            
            scanName = fileBaseName;
            
            if isfield(R, 'ScanName') && ...
                    strlength(string(R.ScanName)) > 0
                
                scanName = char(string(R.ScanName));
                
            elseif isfield(S, 'ProcessingInfo') && ...
                    isstruct(S.ProcessingInfo) && ...
                    isfield(S.ProcessingInfo, 'ScanName') && ...
                    ~isempty(S.ProcessingInfo.ScanName)
                
                scanName = char(S.ProcessingInfo.ScanName);
            end
            
            % =============================================================
            % SCAN ID
            % =============================================================
            scanID = app.makeSafeTextForFileName(scanName);
            
            if isfield(R, 'ScanID') && ...
                    strlength(string(R.ScanID)) > 0
                
                scanID = char(string(R.ScanID));
                
            elseif isfield(S, 'ProcessingInfo') && ...
                    isstruct(S.ProcessingInfo) && ...
                    isfield(S.ProcessingInfo, 'ScanID') && ...
                    ~isempty(S.ProcessingInfo.ScanID)
                
                scanID = char(S.ProcessingInfo.ScanID);
            end
            
            % =============================================================
            % STANDARD PAYLOAD
            % =============================================================
            payload = struct();
            
            payload.ScanName = string(scanName);
            payload.ScanID = string(scanID);
            
            payload.Cube = cube;
            payload.Wavenumber = wn;
            payload.ValidMask = validMask;
            
            payload.SourceMatPath = string(matPath);
            payload.SourceVariable = string(sourceVariable);
            
            payload.SourceApp = "scanspectra";
            payload.SourceMode = "Existing original MAT";
            payload.SourceStage = "Original";
            payload.Stage = "Original prepared Raman scan";
            
            payload.CreatedDate = string( ...
                datestr(now, 'yyyy-mm-dd HH:MM:SS'));
        end
        
        
        function showCurrentOriginalScanInfo(app)
            
            % =============================================================
            % DISPLAY CURRENT ORIGINAL SCAN INFORMATION
            % =============================================================
            
            if ~app.hasOriginalLoadedScan()
                uialert( ...
                    app.UIFigure, ...
                    'No original Raman scan is currently loaded.', ...
                    'No original scan');
                return
            end
            
            P = app.OriginalLoadedScan;
            
            sourcePath = '';
            
            if isfield(P, 'SourceMatPath') && ...
                    strlength(string(P.SourceMatPath)) > 0
                
                sourcePath = char(P.SourceMatPath);
            end
            
            uialert( ...
                app.UIFigure, ...
                sprintf([ ...
                'Current original Raman scan\n\n' ...
                'Scan: %s\n' ...
                'Scan ID: %s\n' ...
                'Cube size: %d x %d x %d\n' ...
                'Valid pixels: %d / %d\n\n' ...
                'Source MAT:\n%s\n\n' ...
                'This original scan remains fixed throughout the workflow.'], ...
                char(P.ScanName), ...
                char(P.ScanID), ...
                size(P.Cube, 1), ...
                size(P.Cube, 2), ...
                size(P.Cube, 3), ...
                nnz(P.ValidMask), ...
                numel(P.ValidMask), ...
                sourcePath), ...
                'Current original scan');
        end
        
        
        function scanFolders = collectReadableScanFolders(app)
            
            readableRoot = fullfile( ...
                app.ScanInputRoot, ...
                'READABLE');
            
            scanFolders = struct( ...
                'Name', {}, ...
                'FolderPath', {}, ...
                'MatPath', {});
            
            if ~isfolder(readableRoot)
                return
            end
            
            items = dir(readableRoot);
            
            count = 0;
            
            for k = 1:numel(items)
                
                if ~items(k).isdir || ...
                        strcmp(items(k).name, '.') || ...
                        strcmp(items(k).name, '..')
                    
                    continue
                end
                
                folderPath = fullfile( ...
                    items(k).folder, ...
                    items(k).name);
                
                matFiles = dir(fullfile( ...
                    folderPath, ...
                    '*_RamanData.mat'));
                
                if numel(matFiles) ~= 1
                    continue
                end
                
                count = count + 1;
                
                scanFolders(count).Name = ...
                    items(k).name;
                
                scanFolders(count).FolderPath = ...
                    folderPath;
                
                scanFolders(count).MatPath = ...
                    fullfile( ...
                    matFiles(1).folder, ...
                    matFiles(1).name);
            end
            
            if ~isempty(scanFolders)
                
                names = string({scanFolders.Name});
                
                [~, order] = sort(lower(names));
                
                scanFolders = scanFolders(order);
            end
        end


        function loadReadableScanByFolder(app)
            
            scanFolders = ...
                app.collectReadableScanFolders();
            
            if isempty(scanFolders)
                
                choice = uiconfirm( ...
                    app.UIFigure, ...
                    { ...
                    'No prepared Raman MAT folder was found in:'; ...
                    ''; ...
                    fullfile(app.ScanInputRoot, 'READABLE'); ...
                    ''; ...
                    'Open Loadscan to prepare a new scan?'}, ...
                    'No prepared MAT file', ...
                    'Options', { ...
                    'Open Loadscan', ...
                    'Cancel'}, ...
                    'DefaultOption', ...
                    'Open Loadscan', ...
                    'CancelOption', ...
                    'Cancel');
                
                if strcmp(choice, 'Open Loadscan')
                    app.openLoadScanApp();
                end
                
                return
            end
            
            folderNames = ...
                {scanFolders.Name};
            
            [idx, ok] = listdlg( ...
                'PromptString', { ...
                'Select a prepared Raman scan folder:'; ...
                ''; ...
                fullfile(app.ScanInputRoot, 'READABLE')}, ...
                'SelectionMode', ...
                'single', ...
                'ListString', ...
                folderNames, ...
                'ListSize', ...
                [440 360], ...
                'Name', ...
                'Prepared Raman scans', ...
                'OKString', ...
                'Load selected scan', ...
                'CancelString', ...
                'Cancel');
            
            if ~ok || isempty(idx)
                return
            end
            
            selectedScan = ...
                scanFolders(idx);
            
            payload = ...
                app.buildOriginalPayloadFromMatFile( ...
                selectedScan.MatPath);
            
            payload.ScanName = ...
                string(selectedScan.Name);
            
            payload.ScanID = ...
                string( ...
                app.makeSafeTextForFileName( ...
                selectedScan.Name));
            
            payload.SourceFolder = ...
                string(selectedScan.FolderPath);
            
            payload.SourceMatPath = ...
                string(selectedScan.MatPath);
            
            payload.SourceMode = ...
                "Existing prepared Raman MAT";
            
            payload.SourceStage = ...
                "Original";
            
            payload.Stage = ...
                "Original prepared Raman scan";
            
            app.receivePreparedScan(payload);
            
            app.addHistory(sprintf( ...
                'Loaded prepared Raman scan: %s', ...
                selectedScan.Name));
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SessionAuthenticated = false;
            app.TextArea.Editable = 'off';
            app.TextArea_2.Editable = 'off';
            app.TextArea.Value = { ...
                'Raman scan mode is waiting for a Home session.'};
            app.TextArea_2.Value = { ...
                'No Home session'};
            app.setScanControlsEnabled(false);
        end

        % Button pushed function: ScanpretreatButton
        function ScanpretreatButtonPushed(app, event)
            % =============================================================
            % 1. REQUIRE MAINHOME SESSION
            % =============================================================
            
            if ~app.ensureSessionIdentity('Scan pre-treat')
                return
            end
            
            app.addHistory('Clicked: Scan pre-treat.');
            
            % =============================================================
            % 2. REQUIRE AN ORIGINAL SCAN
            % =============================================================
            
            if ~app.hasOriginalLoadedScan()
                
                uialert( ...
                    app.UIFigure, ...
                    sprintf([ ...
                    'No original Raman scan is currently loaded.\n\n' ...
                    'Please press Load Raman scan first, then select or prepare ' ...
                    'a Raman scan before opening Scan pre-treat.']), ...
                    'No Raman scan loaded');
                
                app.addHistory( ...
                    'Cannot open scanpretreat: no OriginalLoadedScan.');
                
                return
            end
            
            try
                
                % =========================================================
                % 3. BUILD SESSION AND INPUT PAYLOAD
                % =========================================================
                
                scanSession = ...
                    app.buildScanHomeSessionInfo();
                
                inputPayload = ...
                    app.OriginalLoadedScan;
                
                % OriginalLoadedScan must remain fixed in scanspectra.
                inputPayload.SourceApp = ...
                    "scanspectra";
                
                inputPayload.SourceStage = ...
                    "Original";
                
                inputPayload.Stage = ...
                    "Original prepared Raman scan";
                
                % =========================================================
                % 4. OPEN OR REUSE SCANPRETREAT
                % =========================================================
                
                if isempty(app.scanPretreatApp) || ...
                        ~isvalid(app.scanPretreatApp)
                    
                    app.scanPretreatApp = ...
                        scanpretreat();
                    
                    app.scanPretreatApp.receiveScanHomeSession( ...
                        scanSession, ...
                        app);
                    
                else
                    
                    app.scanPretreatApp.UIFigure.Visible = ...
                        'on';
                    
                    try
                        figure(app.scanPretreatApp.UIFigure);
                    catch
                    end
                end
                
                % =========================================================
                % 5. ALWAYS SEND THE CURRENT ORIGINAL SCAN
                %
                % This also reloads scanpretreat correctly when the user
                % changes the original scan in scanspectra.
                % =========================================================
                
                if ~ismethod( ...
                        app.scanPretreatApp, ...
                        'receiveOriginalScan')
                    
                    error([ ...
                        'scanpretreat does not contain receiveOriginalScan().' ...
                        newline ...
                        'Add the receiveOriginalScan block to scanpretreat first.']);
                end
                
                app.scanPretreatApp.receiveOriginalScan( ...
                    inputPayload, ...
                    app);
                
                app.scanPretreatApp.UIFigure.Visible = ...
                    'on';
                
                try
                    figure(app.scanPretreatApp.UIFigure);
                catch
                end
                
                app.addHistory(sprintf( ...
                    'Opened scanpretreat with original scan: %s', ...
                    char(inputPayload.ScanName)));
                
            catch ME
                
                app.addHistory( ...
                    ['ERROR opening scanpretreat: ' ME.message]);
                
                uialert( ...
                    app.UIFigure, ...
                    ME.message, ...
                    'scanpretreat error');
            end
        end

        % Button pushed function: SinglespectraextractionButton
        function SinglespectraextractionButtonPushed(app, event)
            % % =============================================================
            % % 1. REQUIRE MAINHOME SESSION
            % % =============================================================
            %
            % if ~app.ensureSessionIdentity( ...
            %         'Single spectra extraction')
            %
            %     return
            % end
            %
            % app.addHistory( ...
            %     'Clicked: Single spectra extraction.');
            %
            % % =============================================================
            % % 2. EXPLAIN THE TWO VALID ENTRY PATHS
            % %
            % % IMPORTANT:
            % %
            % % Saved-cluster extraction:
            % %   Only for previously completed segmentation/extraction work.
            % %
            % % Start from beginning:
            % %   Required when upstream processing has not been completed.
            % % =============================================================
            %
            % choice = ...
            %     uiconfirm( ...
            %     app.UIFigure, ...
            %     { ...
            %     'Do you want to extract representative single spectra?'; ...
            %     ''; ...
            %     'There are two valid ways to enter this workflow:'; ...
            %     ''; ...
            %     '1. OPEN SAVED CLUSTERS'; ...
            %     ['Use this only if Raman scan preparation, pretreatment, ' ...
            %     'segmentation, cluster review/QC and cluster-package export ' ...
            %     'were already completed previously.']; ...
            %     ''; ...
            %     ['The app will open directly at Single spectra extract, ' ...
            %     'where you can load the saved cluster packages.']; ...
            %     ''; ...
            %     '2. START FROM BEGINNING'; ...
            %     ['Choose this when the upstream workflow has not been fully ' ...
            %     'completed or when you are unsure.']; ...
            %     ''; ...
            %     ['The Raman scan must then be loaded/prepared and processed ' ...
            %     'through the normal workflow before extraction.']; ...
            %     ''; ...
            %     ['Do not use saved-cluster extraction to bypass unfinished ' ...
            %     'preprocessing, segmentation or cluster QC.']}, ...
            %     'Single spectra extraction', ...
            %     'Options', { ...
            %     'Open saved clusters', ...
            %     'Start from beginning', ...
            %     'Cancel'}, ...
            %     'DefaultOption', ...
            %     'Start from beginning', ...
            %     'CancelOption', ...
            %     'Cancel');
            %
            % % =============================================================
            % % 3. USER CANCELLED
            % % =============================================================
            %
            % if isempty(choice) || ...
            %         strcmp(choice, 'Cancel')
            %
            %     app.addHistory( ...
            %         'Single spectra extraction entry cancelled.');
            %
            %     return
            % end
            %
            % % =============================================================
            % % 4. START FROM BEGINNING
            % %
            % % Do not try to reuse ActiveLoadedScan here.
            % %
            % % Starting from beginning means returning to the official Raman
            % % scan input workflow.
            % % =============================================================
            %
            % if strcmp(choice, 'Start from beginning')
            %
            %     app.addHistory( ...
            %         ['Single spectra extraction: user selected ' ...
            %         'Start from beginning.']);
            %
            %     % Reuse the official Load Raman scan workflow.
            %     %
            %     % That workflow already asks:
            %     %   - use prepared Raman MAT
            %     %   - or open Loadscan
            %     %
            %     % Therefore no duplicate loader logic is required here.
            %     app.LoadRamanscanButtonPushed([]);
            %
            %     return
            % end
            %
            % % =============================================================
            % % 5. OPEN SAVED CLUSTER EXTRACTION
            % % =============================================================
            %
            % if ~strcmp(choice, 'Open saved clusters')
            %     return
            % end
            %
            % app.addHistory( ...
            %     ['Single spectra extraction: user confirmed that upstream ' ...
            %     'segmentation and cluster-package export were already completed.']);
            %
            % app.UIFigure.Pointer = ...
            %     'watch';
            %
            % drawnow;
            %
            % try
            %
            %     % =========================================================
            %     % 6. CREATE OR REUSE single_extract
            %     % =========================================================
            %
            %     if isempty(app.scanSingleExtractApp) || ...
            %             ~isvalid(app.scanSingleExtractApp)
            %
            %         app.scanSingleExtractApp = ...
            %             single_extract();
            %     end
            %
            %     % =========================================================
            %     % 7. REQUIRE DIRECT EXTRACTION ENTRY METHOD
            %     % =========================================================
            %
            %     if ~ismethod( ...
            %             app.scanSingleExtractApp, ...
            %             'openSavedClusterExtractionMode')
            %
            %         error([ ...
            %             'single_extract does not contain ' ...
            %             'openSavedClusterExtractionMode().' ...
            %             newline newline ...
            %             ['Add the saved-cluster direct-entry method to ' ...
            %             'single_extract first.']]);
            %     end
            %
            %     % =========================================================
            %     % 8. OPEN DIRECTLY AT SAVED-CLUSTER EXTRACTION TAB
            %     %
            %     % No ActiveLoadedScan payload is sent.
            %     % No segmentation cube is loaded.
            %     % No upstream state is silently assumed.
            %     % =========================================================
            %
            %     app.scanSingleExtractApp.openSavedClusterExtractionMode( ...
            %         app);
            %
            %     % =========================================================
            %     % 9. HISTORY
            %     % =========================================================
            %
            %     app.addHistory( ...
            %         ['Opened single_extract directly at saved-cluster ' ...
            %         'representative extraction mode.']);
            %
            %     app.addHistory( ...
            %         ['User must load previously generated cluster packages ' ...
            %         'using Load clusters.']);
            %
            %     % =========================================================
            %     % 10. HIDE PARENT ONLY AFTER CHILD OPENED
            %     % =========================================================
            %
            %     app.UIFigure.Visible = ...
            %         'off';
            %
            % catch ME
            %
            %     app.addHistory( ...
            %         ['ERROR opening saved-cluster extraction: ' ...
            %         ME.message]);
            %
            %     uialert( ...
            %         app.UIFigure, ...
            %         getReport( ...
            %         ME, ...
            %         'extended', ...
            %         'hyperlinks', ...
            %         'off'), ...
            %         'Single spectra extraction error');
            % end
            %
            % app.UIFigure.Pointer = ...
            %     'arrow';


            % =============================================================
            % SINGLE SPECTRA EXTRACTION ENTRY
            %
            % Two independent workflows:
            %
            %   1. CURRENT PRETREATED SCAN
            %      ActiveLoadedScan
            %          -> single_extract
            %          -> Cell-background segmentation
            %
            %   2. SAVED CLUSTER PACKAGES
            %      previously completed segmentation
            %          -> single_extract
            %          -> Single spectra extract tab
            % =============================================================
            
            
            % =============================================================
            % 1. REQUIRE SESSION
            % =============================================================
            
            if ~app.ensureSessionIdentity( ...
                    'Single spectra extraction')
                
                return
            end
            
            
            % =============================================================
            % 2. CHECK WHETHER A CURRENT PRETREATED SCAN EXISTS
            % =============================================================
            
            hasActivePretreatedScan = ...
                ~isempty(app.ActiveLoadedScan) && ...
                isstruct(app.ActiveLoadedScan) && ...
                isfield(app.ActiveLoadedScan, 'Cube') && ...
                ~isempty(app.ActiveLoadedScan.Cube) && ...
                isfield(app.ActiveLoadedScan, 'Wavenumber') && ...
                ~isempty(app.ActiveLoadedScan.Wavenumber);
            
            
            % =============================================================
            % 3. CHOOSE ENTRY MODE
            % =============================================================
            
            if hasActivePretreatedScan
                
                scanName = ...
                    'Current scan';
                
                if isfield(app.ActiveLoadedScan, 'ScanName') && ...
                        ~isempty(app.ActiveLoadedScan.ScanName)
                    
                    scanName = ...
                        char(string(app.ActiveLoadedScan.ScanName));
                end
                
                
                stageName = ...
                    'Pretreated';
                
                if isfield(app.ActiveLoadedScan, 'Stage') && ...
                        ~isempty(app.ActiveLoadedScan.Stage)
                    
                    stageName = ...
                        char(string(app.ActiveLoadedScan.Stage));
                end
                
                
                choice = ...
                    uiconfirm( ...
                    app.UIFigure, ...
                    { ...
                    'A pretreated Raman scan is currently active.'; ...
                    ''; ...
                    ['Scan: ' scanName]; ...
                    ['Stage: ' stageName]; ...
                    ''; ...
                    'CURRENT PRETREATED SCAN'; ...
                    ['Continue directly to Cell-Background segmentation ' ...
                    'using the active pretreated scan.']; ...
                    ''; ...
                    'SAVED CLUSTERS'; ...
                    ['Open previously saved cluster packages directly in ' ...
                    'the representative-extraction tab.']}, ...
                    'Single spectra extraction', ...
                    'Options', { ...
                    'Use current scan', ...
                    'Open saved clusters', ...
                    'Cancel'}, ...
                    'DefaultOption', ...
                    'Use current scan', ...
                    'CancelOption', ...
                    'Cancel');
                
            else
                
                choice = ...
                    uiconfirm( ...
                    app.UIFigure, ...
                    { ...
                    'No active pretreated Raman scan is available.'; ...
                    ''; ...
                    'You can either:'; ...
                    ''; ...
                    'LOAD RAMAN SCAN'; ...
                    ['Load a scan and run pretreatment before ' ...
                    'Cell-Background segmentation.']; ...
                    ''; ...
                    'SAVED CLUSTERS'; ...
                    ['Open previously completed cluster packages directly ' ...
                    'for representative extraction.']}, ...
                    'Single spectra extraction', ...
                    'Options', { ...
                    'Load Raman scan', ...
                    'Open saved clusters', ...
                    'Cancel'}, ...
                    'DefaultOption', ...
                    'Load Raman scan', ...
                    'CancelOption', ...
                    'Cancel');
            end
            
            
            % =============================================================
            % 4. CANCEL
            % =============================================================
            
            if isempty(choice) || ...
                    strcmp(choice, 'Cancel')
                
                app.addHistory( ...
                    'Single spectra extraction entry cancelled.');
                
                return
            end
            
            
            % =============================================================
            % 5. NO ACTIVE SCAN -> LOAD RAMAN SCAN
            % =============================================================
            
            if strcmp(choice, 'Load Raman scan')
                
                app.addHistory( ...
                    ['Single spectra extraction requested without an ' ...
                    'active pretreated scan. Opening Raman scan loader.']);
                
                app.LoadRamanscanButtonPushed([]);
                
                return
            end
            
            
            % =============================================================
            % 6. CURRENT PRETREATED SCAN
            %
            % This is the normal live workflow after scanpretreat.
            % =============================================================
            
            if strcmp(choice, 'Use current scan')
                
                app.UIFigure.Pointer = ...
                    'watch';
                
                drawnow;
                
                try
                    
                    % =========================================================
                    % BUILD PAYLOAD FROM ActiveLoadedScan
                    % =========================================================
                    
                    [payload, ok] = ...
                        app.buildSingleExtractionPayload();
                    
                    if ~ok
                        
                        app.UIFigure.Pointer = ...
                            'arrow';
                        
                        return
                    end
                    
                    
                    % =========================================================
                    % STORAGE CONTRACT
                    % =========================================================
                    
                    scanID = ...
                        app.makeSafeTextForFileName( ...
                        payload.ScanID);
                    
                    
                    workspacePath = ...
                        '';
                    
                    if isfield(payload, 'WorkspacePath') && ...
                            strlength(string(payload.WorkspacePath)) > 0
                        
                        workspacePath = ...
                            char(string(payload.WorkspacePath));
                    end
                    
                    
                    if isempty(workspacePath)
                        
                        workspacePath = ...
                            fullfile( ...
                            app.ScanWorkspaceRoot, ...
                            scanID);
                    end
                    
                    
                    singleExtractRoot = ...
                        fullfile( ...
                        workspacePath, ...
                        '04_single_extract');
                    
                    
                    if ~isfolder(singleExtractRoot)
                        
                        mkdir(singleExtractRoot);
                    end
                    
                    
                    payload.ProjectRoot = ...
                        string(app.ProjectRoot);
                    
                    payload.DataInputRoot = ...
                        string(app.DataInputRoot);
                    
                    payload.DataOutputRoot = ...
                        string(app.DataOutputRoot);
                    
                    payload.ScanInputRoot = ...
                        string(app.ScanInputRoot);
                    
                    payload.ScanOutputRoot = ...
                        string(app.ScanOutputRoot);
                    
                    payload.WorkspacePath = ...
                        string(workspacePath);
                    
                    payload.ManifestPath = ...
                        string( ...
                        fullfile( ...
                        workspacePath, ...
                        'manifest.mat'));
                    
                    payload.OutputRoot = ...
                        string(singleExtractRoot);
                    
                    payload.SingleInputRoot = ...
                        string( ...
                        fullfile( ...
                        app.DataInputRoot, ...
                        'single'));
                    
                    payload.ReadableRoot = ...
                        string( ...
                        fullfile( ...
                        app.ScanInputRoot, ...
                        'READABLE'));
                    
                    
                    % =========================================================
                    % CREATE OR REUSE single_extract
                    % =========================================================
                    
                    if isempty(app.scanSingleExtractApp) || ...
                            ~isvalid(app.scanSingleExtractApp)
                        
                        app.scanSingleExtractApp = ...
                            single_extract();
                    end
                    
                    
                    % =========================================================
                    % REQUIRE LIVE-SCAN RECEIVER
                    % =========================================================
                    
                    if ~ismethod( ...
                            app.scanSingleExtractApp, ...
                            'receiveScanForSegmentation')
                        
                        error( ...
                            'scanspectra:MissingSingleExtractReceiver', ...
                            ['single_extract does not contain ' ...
                            'receiveScanForSegmentation().']);
                    end
                    
                    
                    % =========================================================
                    % SEND ACTIVE PRETREATED SCAN
                    %
                    % single_extract will:
                    %
                    %   - receive Cube
                    %   - receive RawCube
                    %   - receive ValidMask
                    %   - receive ArtifactMask2D
                    %   - reset previous segmentation state
                    %   - open Cell-background segmentation tab
                    % =========================================================
                    
                    app.scanSingleExtractApp.receiveScanForSegmentation( ...
                        payload, ...
                        app);
                    
                    
                    % =========================================================
                    % SHOW CHILD APP
                    % =========================================================
                    
                    app.scanSingleExtractApp.UIFigure.Visible = ...
                        'on';
                    
                    try
                        
                        figure( ...
                            app.scanSingleExtractApp.UIFigure);
                        
                    catch
                    end
                    
                    
                    % =========================================================
                    % HIDE SCANSPECTRA
                    % =========================================================
                    
                    app.UIFigure.Visible = ...
                        'off';
                    
                    
                    % =========================================================
                    % HISTORY
                    % =========================================================
                    
                    app.addHistory(sprintf( ...
                        ['Opened Cell-Background segmentation using ' ...
                        'active pretreated scan: %s'], ...
                        char(payload.ScanName)));
                    
                    app.addHistory(sprintf( ...
                        'Valid pixels transferred: %d', ...
                        nnz(payload.ValidMask)));
                    
                    if isfield(payload, 'ArtifactMask2D')
                        
                        app.addHistory(sprintf( ...
                            'Artifact pixels excluded: %d', ...
                            nnz(payload.ArtifactMask2D)));
                    end
                    
                    
                catch ME
                    
                    app.UIFigure.Pointer = ...
                        'arrow';
                    
                    uialert( ...
                        app.UIFigure, ...
                        ME.message, ...
                        'Single spectra extraction error');
                    
                    return
                end
                
                
                app.UIFigure.Pointer = ...
                    'arrow';
                
                return
            end
            
            
            % =============================================================
            % 7. SAVED CLUSTER MODE
            %
            % Completely independent from the active Raman scan.
            % =============================================================
            
            if strcmp(choice, 'Open saved clusters')
                
                app.UIFigure.Pointer = ...
                    'watch';
                
                drawnow;
                
                try
                    
                    % =========================================================
                    % CREATE OR REUSE single_extract
                    % =========================================================
                    
                    if isempty(app.scanSingleExtractApp) || ...
                            ~isvalid(app.scanSingleExtractApp)
                        
                        app.scanSingleExtractApp = ...
                            single_extract();
                    end
                    
                    
                    % =========================================================
                    % REQUIRE SAVED-CLUSTER ENTRY
                    % =========================================================
                    
                    if ~ismethod( ...
                            app.scanSingleExtractApp, ...
                            'openSavedClusterExtractionMode')
                        
                        error( ...
                            ['single_extract does not contain ' ...
                            'openSavedClusterExtractionMode().']);
                    end
                    
                    
                    % =========================================================
                    % OPEN SAVED-CLUSTER EXTRACTION TAB
                    % =========================================================
                    
                    app.scanSingleExtractApp.openSavedClusterExtractionMode( ...
                        app);
                    
                    
                    % =========================================================
                    % SHOW APP
                    % =========================================================
                    
                    app.scanSingleExtractApp.UIFigure.Visible = ...
                        'on';
                    
                    try
                        
                        figure( ...
                            app.scanSingleExtractApp.UIFigure);
                        
                    catch
                    end
                    
                    
                    app.UIFigure.Visible = ...
                        'off';
                    
                    
                    app.addHistory( ...
                        ['Opened single_extract directly at saved-cluster ' ...
                        'representative extraction mode.']);
                    
                    
                catch ME
                    
                    app.UIFigure.Pointer = ...
                        'arrow';
                    
                    uialert( ...
                        app.UIFigure, ...
                        ME.message, ...
                        'Saved cluster extraction error');
                    
                    return
                end
                
                
                app.UIFigure.Pointer = ...
                    'arrow';
                
                return
            end
            
        end

        % Button pushed function: SinglespectraanalysisButton
        function SinglespectraanalysisButtonPushed(app, event)
            if ~app.ensureSessionIdentity('Single spectra analysis')
                return
            end
            app.addHistory('Clicked: Single spectra analysis');
            uialert(app.UIFigure, ...
                'Single spectra analysis from scan output has not been connected yet.', ...
                'Not connected');
        end

        % Button pushed function: ScananalysisButton
        function ScananalysisButtonPushed(app, event)
            % =============================================================
            % REQUIRE ACTIVE HOME SESSION
            % =============================================================
            if ~app.ensureSessionIdentity('Scan analysis')
                return
            end
            
            app.addHistory( ...
                'Clicked: Scan analysis / relative Raman unmixing.');
            
            try
                % =========================================================
                % BUILD BACKGROUND-CONFIRMED INPUT
                % =========================================================
                payload = ...
                    app.buildUnmixingPayload();
                
                sessionInfo = ...
                    app.buildScanHomeSessionInfo();
                
                % =========================================================
                % OPEN OR RELOAD UNMIXING APP
                % =========================================================
                if isempty(app.unmixingApp) || ...
                        ~isvalid(app.unmixingApp)
                    
                    app.unmixingApp = ...
                        unmixing();
                    
                    app.unmixingApp.receiveData( ...
                        app, ...
                        payload, ...
                        sessionInfo);
                    
                else
                    
                    % Reload every time because the active scan may have changed.
                    app.unmixingApp.receiveData( ...
                        app, ...
                        payload, ...
                        sessionInfo);
                    
                    app.unmixingApp.UIFigure.Visible = 'on';
                    
                    try
                        figure(app.unmixingApp.UIFigure);
                    catch
                    end
                end
                
                app.addHistory(sprintf( ...
                    ['Opened relative Raman unmixing | ', ...
                    'scan=%s | cell pixels=%d'], ...
                    char(payload.ScanName), ...
                    nnz(payload.ValidMask)));
                
                % Hide parent while child analysis app is active.
                app.UIFigure.Visible = 'off';
                
            catch ME
                
                report = getReport( ...
                    ME, ...
                    'extended', ...
                    'hyperlinks', ...
                    'off');
                
                app.addHistory( ...
                    ['ERROR opening unmixing: ' ...
                    ME.message]);
                
                uialert( ...
                    app.UIFigure, ...
                    report, ...
                    'Unmixing input error');
            end
            
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            if ~app.ensureSessionIdentity( ...
                    'Finish Raman scan app')
                
                return
            end
            
            app.addHistory('Clicked: Finish');
            
            selection = uiconfirm(app.UIFigure, ...
                { ...
                'What do you want to do?'; ...
                ''; ...
                ['Current scan log: ' app.SessionLogPath]}, ...
                'Finish Raman scan', ...
                'Options', { ...
                'Close Raman scan', ...
                'Return Home', ...
                'Cancel'}, ...
                'DefaultOption', ...
                'Return Home', ...
                'CancelOption', ...
                'Cancel');
            
            switch selection
                
                case 'Close Raman scan'
                    
                    app.addHistory( ...
                        'Raman scan app closed by user.');
                    
                    app.logToHome( ...
                        'Close Raman scan', ...
                        'Raman scan child app closed.');
                    
                    delete(app);
                    
                case 'Return Home'
                    
                    app.addHistory( ...
                        'Returning to Home.');
                    
                    app.logToHome( ...
                        'Return Home', ...
                        'Returned from Raman scan workspace.');
                    
                    if ~isempty(app.mainHomeApp) && ...
                            isvalid(app.mainHomeApp)
                        
                        if ismethod(app.mainHomeApp, ...
                                'showHomeFromChild')
                            
                            app.mainHomeApp.showHomeFromChild( ...
                                'Raman scan');
                            
                        else
                            
                            app.mainHomeApp.UIFigure.Visible = 'on';
                        end
                        
                    else
                        
                        app.mainHomeApp = Home();
                        app.mainHomeApp.UIFigure.Visible = 'on';
                    end
                    
                    app.UIFigure.Visible = 'off';
                    
                otherwise
                    
                    app.addHistory( ...
                        'Finish cancelled.');
            end
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
            
        end

        % Value changed function: TextArea_2
        function TextArea_2ValueChanged(app, event)
            value = app.TextArea_2.Value;
            
        end

        % Button pushed function: MainhomeButton
        function MainhomeButtonPushed(app, event)
            if app.SessionAuthenticated
                
                app.addHistory( ...
                    'Clicked: Main home');
                
                app.logToHome( ...
                    'Return Home', ...
                    'Returned from Raman scan workspace.');
                
            end
            
            try
                
                if ~isempty(app.mainHomeApp) && ...
                        isvalid(app.mainHomeApp) && ...
                        isvalid(app.mainHomeApp.UIFigure)
                    
                    if ismethod(app.mainHomeApp, 'showHomeFromChild')
                        
                        app.mainHomeApp.showHomeFromChild( ...
                            'Raman scan');
                        
                    else
                        
                        app.mainHomeApp.UIFigure.Visible = 'on';
                        
                        try
                            figure(app.mainHomeApp.UIFigure);
                        catch
                        end
                    end
                    
                    app.UIFigure.Visible = 'off';
                    
                else
                    
                    % Fallback only when the original Home object no longer exists.
                    homeApp = Home();
                    homeApp.UIFigure.Visible = 'on';
                    
                    app.mainHomeApp = homeApp;
                    app.UIFigure.Visible = 'off';
                end
                
            catch ME
                
                uialert(app.UIFigure, ...
                    ME.message, ...
                    'Main home error');
            end
        end

        % Button pushed function: LoadRamanscanButton
        function LoadRamanscanButtonPushed(app, event)
            if ~app.ensureSessionIdentity('Load Raman scan')
                return
            end
            
            choice = uiconfirm( ...
                app.UIFigure, ...
                { ...
                'Do you already have a prepared Raman MAT file?'; ...
                ''; ...
                'Prepared MAT location:'; ...
                fullfile(app.ScanInputRoot, 'READABLE')}, ...
                'Load Raman scan', ...
                'Options', { ...
                'Yes, select MAT', ...
                'No, open Loadscan', ...
                'Cancel'}, ...
                'DefaultOption', ...
                'Yes, select MAT', ...
                'CancelOption', ...
                'Cancel');
            
            switch choice
                
                case 'Yes, select MAT'
                    
                    app.loadReadableScanByFolder();
                    
                case 'No, open Loadscan'
                    
                    app.openLoadScanApp();
                    
                otherwise
                    
                    app.addHistory( ...
                        'Load Raman scan cancelled.');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ScanningtospectraanalysisLabel = uilabel(app.GridLayout);
            app.ScanningtospectraanalysisLabel.Text = 'Scanning to spectra analysis';
            app.ActivityhistoryLabel = uilabel(app.GridLayout);
            app.ActivityhistoryLabel.Text = 'Activity history';
            app.TextArea = uitextarea(app.GridLayout);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.TextArea_2 = uitextarea(app.GridLayout);
            app.TextArea_2.ValueChangedFcn = createCallbackFcn(app, @TextArea_2ValueChanged, true);
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.ScanpretreatButton = uibutton(app.GridLayout2, 'push');
            app.ScanpretreatButton.ButtonPushedFcn = createCallbackFcn(app, @ScanpretreatButtonPushed, true);
            app.ScanpretreatButton.Text = 'Scan pre-treat';
            app.SinglespectraextractionButton = uibutton(app.GridLayout2, 'push');
            app.SinglespectraextractionButton.ButtonPushedFcn = createCallbackFcn(app, @SinglespectraextractionButtonPushed, true);
            app.SinglespectraextractionButton.Text = 'Single spectra extraction';
            app.SinglespectraanalysisButton = uibutton(app.GridLayout2, 'push');
            app.SinglespectraanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @SinglespectraanalysisButtonPushed, true);
            app.SinglespectraanalysisButton.Text = 'Single spectra analysis';
            app.ScananalysisButton = uibutton(app.GridLayout2, 'push');
            app.ScananalysisButton.ButtonPushedFcn = createCallbackFcn(app, @ScananalysisButtonPushed, true);
            app.ScananalysisButton.Text = 'Scan analysis';
            app.LoadRamanscanButton = uibutton(app.GridLayout2, 'push');
            app.LoadRamanscanButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRamanscanButtonPushed, true);
            app.LoadRamanscanButton.Text = 'Load Raman scan';
            app.Panel_2 = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.Panel_2);
            app.Image = uiimage(app.GridLayout3);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo_icube.png');
            app.Image2 = uiimage(app.GridLayout3);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'batch_Layer 1.png');
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.FinishButton = uibutton(app.GridLayout4, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.MainhomeButton = uibutton(app.GridLayout4, 'push');
            app.MainhomeButton.ButtonPushedFcn = createCallbackFcn(app, @MainhomeButtonPushed, true);
            app.MainhomeButton.Text = 'Main home';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = scanspectra

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end