classdef sg_baseline < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        SaveimagesButton                matlab.ui.control.Button
        GridLayout7                     matlab.ui.container.GridLayout
        WnfilterButton                  matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        FinishButton                    matlab.ui.control.Button
        Panel_4                         matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        UIAxes3_4                       matlab.ui.control.UIAxes
        Panel_3                         matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        UIAxes3                         matlab.ui.control.UIAxes
        Panel_2                         matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        UIAxes3_5                       matlab.ui.control.UIAxes
        UIAxes3_2                       matlab.ui.control.UIAxes
        Panel                           matlab.ui.container.Panel
        RamanAppBaselinecorrectionLabel  matlab.ui.control.Label
        SpectralevaluationPanel         matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        WorstspectrumButton             matlab.ui.control.Button
        PeakpreservationEditField       matlab.ui.control.EditField
        PeakpreservationLabel           matlab.ui.control.Label
        NegativeresidualratioEditField  matlab.ui.control.EditField
        NegativeresidualratioLabel      matlab.ui.control.Label
        CurvatureEditField              matlab.ui.control.EditField
        CurvatureEditFieldLabel         matlab.ui.control.Label
        ResidualRMSEditField            matlab.ui.control.EditField
        ResidualRMSEditFieldLabel       matlab.ui.control.Label
        Tree                            matlab.ui.container.Tree
        Node                            matlab.ui.container.TreeNode
        Node2                           matlab.ui.container.TreeNode
        Node3                           matlab.ui.container.TreeNode
        Node4                           matlab.ui.container.TreeNode
        BaselineactionPanel             matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        QuickviewButton                 matlab.ui.control.Button
        BaselinecorrectionButton        matlab.ui.control.Button
        BaselinesettingsButton          matlab.ui.control.Button
        MethodsDropDown                 matlab.ui.control.DropDown
        MethodsDropDownLabel            matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        mainApp
        mode
        wn
        spectra
        cube
        correctedSpectra
        correctedCube
        baselineSpectra
        baselineCube
        selectedIndex = 1
        names
        spectralDef
        baselineParams
        metricTable
        % =============================================================
        % QUICK VIEW STATE
        % =============================================================
        quickViewActive = false
        quickViewMethod = ""
        quickViewRaw = []
        quickViewCandidates = struct([])
        quickViewSelectedRank = []
        quickViewTopCount = 5
        
    end
    
    methods (Access = private)
        
        %% ============================================================
        % QUICK VIEW — PARAMETER EXPLORATION
        % =============================================================
        
        function previewSpectrum = getQuickViewSpectrum(app)
            % Representative mean spectrum used for parameter exploration.
            
            switch app.mode
                case 1
                    if isempty(app.spectra)
                        error('No spectra are available.');
                    end
                    
                    previewSpectrum = mean( ...
                        app.spectra, 2, 'omitnan');
                    
                case 2
                    if isempty(app.cube)
                        error('No Raman cube is available.');
                    end
                    
                    spectraMatrix = ...
                        app.cubeToSpectraMatrixLocal(app.cube);
                    
                    previewSpectrum = mean( ...
                        spectraMatrix, 2, 'omitnan');
                    
                otherwise
                    error('Unsupported data mode.');
            end
            
            previewSpectrum = previewSpectrum(:);
            
            if numel(previewSpectrum) ~= numel(app.wn)
                error(['Representative spectrum length does not match ' ...
                    'the wavenumber axis.']);
            end
            
            if all(~isfinite(previewSpectrum))
                error('Representative spectrum contains no finite values.');
            end
        end
        
        
        function parameterSets = buildQuickViewParameterGrid(app)
            % Generate an objective parameter grid.
            %
            % ALS:
            %   Explore lambda × p.
            %   niter and tol remain fixed.
            %
            % ArPLS:
            %   Explore lambda.
            %   ratio and niter remain fixed because they mainly govern
            %   convergence.
            %
            % AirPLS:
            %   Explore lambda × ratio.
            %   niter remains fixed.
            
            method = app.baselineParams.method;
            parameterSets = struct([]);
            
            switch method
                case 'ALS'
                    current = app.baselineParams.ALS;
                    
                    lambdaFactors = 10.^[-1, -0.5, 0, 0.5, 1];
                    pFactors = 10.^[-0.5, 0, 0.5];
                    
                    count = 0;
                    
                    for i = 1:numel(lambdaFactors)
                        for j = 1:numel(pFactors)
                            count = count + 1;
                            
                            p = current;
                            p.lam = max( ...
                                current.lam * lambdaFactors(i), eps);
                            
                            p.p = min(max( ...
                                current.p * pFactors(j), 1e-6), 0.1);
                            
                            parameterSets(count).params = p;
                        end
                    end
                    
                case 'ArPLS'
                    current = app.baselineParams.ArPLS;
                    
                    lambdaFactors = 10.^[ ...
                        -2, -1.5, -1, -0.5, 0, ...
                        0.5, 1, 1.5, 2];
                    
                    for i = 1:numel(lambdaFactors)
                        p = current;
                        p.lam = max( ...
                            current.lam * lambdaFactors(i), eps);
                        
                        parameterSets(i).params = p;
                    end
                    
                case 'AirPLS'
                    current = app.baselineParams.AirPLS;
                    
                    lambdaFactors = 10.^[-1, -0.5, 0, 0.5, 1];
                    ratioFactors = 10.^[-0.5, 0, 0.5];
                    
                    count = 0;
                    
                    for i = 1:numel(lambdaFactors)
                        for j = 1:numel(ratioFactors)
                            count = count + 1;
                            
                            p = current;
                            
                            p.lam = max( ...
                                current.lam * lambdaFactors(i), eps);
                            
                            p.ratio = min(max( ...
                                current.ratio * ratioFactors(j), ...
                                1e-12), 0.5);
                            
                            parameterSets(count).params = p;
                        end
                    end
                    
                case 'Peak stripping'
                    error(sprintf([ ...
                        'Peak stripping parameter exploration is not ' ...
                        'available yet.\n\n' ...
                        'The current external function does not receive ' ...
                        'maxWindow, windowStep, paddingFactor, or ' ...
                        'polyOrder.']));
                    
                otherwise
                    error('Please select a baseline method first.');
            end
        end
        
        
        function [base, corrected] = runQuickViewOneSet( ...
                app, y, method, parameterSet)
            % Run one parameter candidate without permanently changing
            % app.baselineParams.
            
            originalParams = app.baselineParams;
            
            cleanupObject = onCleanup( ...
                @() app.restoreTemporaryBaselineParams(originalParams));
            
            app.baselineParams.method = method;
            
            switch method
                case 'ALS'
                    app.baselineParams.ALS = parameterSet;
                    
                case 'ArPLS'
                    app.baselineParams.ArPLS = parameterSet;
                    
                case 'AirPLS'
                    app.baselineParams.AirPLS = parameterSet;
                    
                otherwise
                    error('Unsupported Quick View method.');
            end
            
            [base, corrected] = ...
                app.runBaselineOneSpectrum(y);
            
            clear cleanupObject
            app.baselineParams = originalParams;
        end
        
        
        function restoreTemporaryBaselineParams(app, originalParams)
            % Used by onCleanup to guarantee restoration after an error.
            
            app.baselineParams = originalParams;
        end
        
        
        function candidates = evaluateQuickViewCandidates( ...
                app, previewSpectrum, parameterSets)
            % Run all candidates and calculate metrics.
            
            method = app.baselineParams.method;
            nCandidates = numel(parameterSets);
            nWn = numel(app.wn);
            
            emptyCandidate = struct( ...
                'params', struct(), ...
                'baseline', nan(nWn,1), ...
                'corrected', nan(nWn,1), ...
                'RMS', NaN, ...
                'Curvature', NaN, ...
                'NegRatio', NaN, ...
                'PeakPres', NaN, ...
                'Score', NaN, ...
                'Rank', NaN, ...
                'Status', "", ...
                'Acceptable', false, ...
                'Label', "");
            
            candidates = repmat( ...
                emptyCandidate, nCandidates, 1);
            
            for k = 1:nCandidates
                candidates(k).params = ...
                    parameterSets(k).params;
                
                [base, corrected] = app.runQuickViewOneSet( ...
                    previewSpectrum, ...
                    method, ...
                    parameterSets(k).params);
                
                candidates(k).baseline = base;
                candidates(k).corrected = corrected;
                
                [ ...
                    candidates(k).RMS, ...
                    candidates(k).Curvature, ...
                    candidates(k).NegRatio, ...
                    candidates(k).PeakPres] = ...
                    app.computeMetricsOneSpectrum( ...
                    previewSpectrum, ...
                    base, ...
                    corrected);
            end
        end
        
        
        function normalizedRanks = metricRankLoss(app, values)
            % Convert metric values into rank-based loss from 0 to 1.
            %
            % Lower original value = lower loss.
            % This is less sensitive to extreme outliers than min-max.
            
            values = values(:);
            normalizedRanks = nan(size(values));
            
            valid = isfinite(values);
            nValid = nnz(valid);
            
            if nValid == 0
                return
            end
            
            if nValid == 1
                normalizedRanks(valid) = 0;
                return
            end
            
            validIndices = find(valid);
            validValues = values(valid);
            
            [~, order] = sort(validValues, 'ascend');
            
            rankValues = zeros(nValid,1);
            rankValues(order) = (0:nValid-1)';
            
            normalizedRanks(validIndices) = ...
                rankValues ./ (nValid - 1);
        end
        
        
        function candidates = rankQuickViewCandidates(app, candidates)
            % Rank candidates using multiple independent metrics.
            %
            % Metric weights:
            %   Residual RMS             30%
            %   Baseline curvature       20%
            %   Negative residual ratio  25%
            %   Peak preservation loss   25%
            %
            % Missing metrics are excluded and remaining weights are
            % renormalized for each candidate.
            
            n = numel(candidates);
            
            RMS = reshape([candidates.RMS], [], 1);
            Curvature = reshape([candidates.Curvature], [], 1);
            NegRatio = reshape([candidates.NegRatio], [], 1);
            PeakPres = reshape([candidates.PeakPres], [], 1);
            
            peakLoss = abs(PeakPres - 1);
            
            rmsRank = app.metricRankLoss(RMS);
            curvatureRank = app.metricRankLoss(Curvature);
            negativeRank = app.metricRankLoss(NegRatio);
            peakRank = app.metricRankLoss(peakLoss);
            
            weights = [0.30, 0.20, 0.25, 0.25];
            
            score = nan(n,1);
            acceptable = false(n,1);
            
            for k = 1:n
                losses = [ ...
                    rmsRank(k), ...
                    curvatureRank(k), ...
                    negativeRank(k), ...
                    peakRank(k)];
                
                validLoss = isfinite(losses);
                
                if ~any(validLoss)
                    score(k) = Inf;
                    continue
                end
                
                localWeights = weights(validLoss);
                localWeights = localWeights ./ sum(localWeights);
                
                score(k) = sum( ...
                    localWeights .* losses(validLoss));
                
                % Acceptance criteria
                negativeOK = ...
                    ~isfinite(NegRatio(k)) || NegRatio(k) <= 0.15;
                
                peakOK = ...
                    ~isfinite(PeakPres(k)) || ...
                    (PeakPres(k) >= 0.80 && PeakPres(k) <= 1.20);
                
                finiteCorrection = ...
                    any(isfinite(candidates(k).corrected)) && ...
                    any(isfinite(candidates(k).baseline));
                
                acceptable(k) = ...
                    negativeOK && peakOK && finiteCorrection;
                
                % Penalize candidates outside acceptance limits.
                if isfinite(NegRatio(k)) && NegRatio(k) > 0.15
                    score(k) = score(k) + ...
                        min(2, 4 * (NegRatio(k) - 0.15));
                end
                
                if isfinite(PeakPres(k))
                    if PeakPres(k) < 0.80
                        score(k) = score(k) + ...
                            min(2, 4 * (0.80 - PeakPres(k)));
                        
                    elseif PeakPres(k) > 1.20
                        score(k) = score(k) + ...
                            min(2, 4 * (PeakPres(k) - 1.20));
                    end
                end
            end
            
            [~, order] = sort(score, 'ascend');
            candidates = candidates(order);
            
            for rankIndex = 1:n
                candidates(rankIndex).Score = ...
                    score(order(rankIndex));
                
                candidates(rankIndex).Rank = rankIndex;
                
                candidates(rankIndex).Acceptable = ...
                    acceptable(order(rankIndex));
                
                if rankIndex <= app.quickViewTopCount && ...
                        candidates(rankIndex).Acceptable
                    
                    candidates(rankIndex).Status = ...
                        "RECOMMENDED";
                    
                elseif candidates(rankIndex).Acceptable
                    candidates(rankIndex).Status = ...
                        "ACCEPTABLE";
                    
                else
                    candidates(rankIndex).Status = ...
                        "REVIEW";
                end
                
                candidates(rankIndex).Label = ...
                    app.formatQuickViewCandidateLabel( ...
                    candidates(rankIndex));
            end
        end
        
        
        function txt = formatQuickViewCandidateLabel(app, candidate)
            % Short Tree label for one candidate.
            
            method = app.quickViewMethod;
            p = candidate.params;
            
            switch method
                case 'ALS'
                    parameterText = sprintf( ...
                        '\\lambda=%.3g | p=%.3g', ...
                        p.lam, p.p);
                    
                case 'ArPLS'
                    parameterText = sprintf( ...
                        '\\lambda=%.3g', ...
                        p.lam);
                    
                case 'AirPLS'
                    parameterText = sprintf( ...
                        '\\lambda=%.3g | ratio=%.3g', ...
                        p.lam, p.ratio);
                    
                otherwise
                    parameterText = '';
            end
            
            txt = sprintf( ...
                'Rank %02d | %s | score=%.3f', ...
                candidate.Rank, ...
                parameterText, ...
                candidate.Score);
        end
        
        
        function populateQuickViewTree(app)
            % Replace the ordinary spectrum Tree with ranked candidates.
            %
            % TreeNode in some MATLAB releases does not support Tooltip,
            % so all essential information is placed directly in Node.Text
            % and in the existing metric fields when a node is selected.
            
            delete(app.Tree.Children);
            
            nCandidates = numel(app.quickViewCandidates);
            
            root = uitreenode(app.Tree);
            root.Text = sprintf( ...
                '%s parameter exploration (%d)', ...
                char(app.quickViewMethod), ...
                nCandidates);
            
            root.NodeData = struct( ...
                'type', 'quick_root');
            
            recommendedRoot = uitreenode(root);
            recommendedRoot.Text = 'Recommended';
            recommendedRoot.NodeData = struct( ...
                'type', 'quick_group');
            
            acceptableRoot = uitreenode(root);
            acceptableRoot.Text = 'Acceptable';
            acceptableRoot.NodeData = struct( ...
                'type', 'quick_group');
            
            reviewRoot = uitreenode(root);
            reviewRoot.Text = 'Review / poor';
            reviewRoot.NodeData = struct( ...
                'type', 'quick_group');
            
            firstCandidateNode = [];
            
            for k = 1:nCandidates
                candidate = app.quickViewCandidates(k);
                
                switch candidate.Status
                    case "RECOMMENDED"
                        parentNode = recommendedRoot;
                        
                    case "ACCEPTABLE"
                        parentNode = acceptableRoot;
                        
                    otherwise
                        parentNode = reviewRoot;
                end
                
                node = uitreenode(parentNode);
                
                node.Text = candidate.Label;
                
                node.NodeData = struct( ...
                    'type', 'quick_candidate', ...
                    'rankIndex', k);
                
                if isempty(firstCandidateNode)
                    firstCandidateNode = node;
                end
            end
            
            if isempty(recommendedRoot.Children)
                recommendedRoot.Text = ...
                    'Recommended — none passed all limits';
            end
            
            if isempty(acceptableRoot.Children)
                acceptableRoot.Text = ...
                    'Acceptable — none';
            end
            
            if isempty(reviewRoot.Children)
                reviewRoot.Text = ...
                    'Review / poor — none';
            end
            
            expand(root);
            
            if ~isempty(recommendedRoot.Children)
                expand(recommendedRoot);
            end
            
            if ~isempty(acceptableRoot.Children)
                expand(acceptableRoot);
            end
            
            app.quickViewSelectedRank = 1;
            
            if ~isempty(firstCandidateNode)
                app.Tree.SelectedNodes = firstCandidateNode;
            end
        end


        function txt = getQuickViewParameterText(app, parameterSet)
            % Full parameter text for titles and tooltips.
            
            switch app.quickViewMethod
                case 'ALS'
                    txt = sprintf([ ...
                        'ALS: lambda=%.4g, p=%.4g, ' ...
                        'niter=%d, tol=%.4g'], ...
                        parameterSet.lam, ...
                        parameterSet.p, ...
                        parameterSet.niter, ...
                        parameterSet.tol);
                    
                case 'ArPLS'
                    txt = sprintf([ ...
                        'ArPLS: lambda=%.4g, ratio=%.4g, ' ...
                        'niter=%d'], ...
                        parameterSet.lam, ...
                        parameterSet.ratio, ...
                        parameterSet.niter);
                    
                case 'AirPLS'
                    txt = sprintf([ ...
                        'AirPLS: lambda=%.4g, ratio=%.4g, ' ...
                        'niter=%d'], ...
                        parameterSet.lam, ...
                        parameterSet.ratio, ...
                        parameterSet.niter);
                    
                otherwise
                    txt = char(app.quickViewMethod);
            end
        end
        
        
        function showQuickViewCandidate(app, selectedRank)
            % Display top-ranked candidates together.
            %
            % UIAxes:
            %   Representative raw spectrum + candidate baselines.
            %
            % UIAxes2:
            %   Corrected candidate spectra.
            %
            % The selected candidate is emphasized.
            
            if ~app.quickViewActive || ...
                    isempty(app.quickViewCandidates)
                return
            end
            
            nCandidates = numel(app.quickViewCandidates);
            
            selectedRank = max( ...
                1, min(selectedRank, nCandidates));
            
            app.quickViewSelectedRank = selectedRank;
            
            topCount = min( ...
                app.quickViewTopCount, nCandidates);
            
            displayRanks = 1:topCount;
            
            if ~ismember(selectedRank, displayRanks)
                displayRanks(end+1) = selectedRank;
            end
            
            selectedCandidate = ...
                app.quickViewCandidates(selectedRank);
            
            %% Axis 1: raw + baselines
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            
            plot( ...
                app.UIAxes, ...
                app.wn, ...
                app.quickViewRaw, ...
                'k', ...
                'LineWidth', 1.6, ...
                'DisplayName', 'Mean raw spectrum');
            
            for rankIndex = displayRanks
                candidate = ...
                    app.quickViewCandidates(rankIndex);
                
                valid = isfinite(candidate.baseline);
                
                if rankIndex == selectedRank
                    plot( ...
                        app.UIAxes, ...
                        app.wn(valid), ...
                        candidate.baseline(valid), ...
                        'r', ...
                        'LineWidth', 2.2, ...
                        'DisplayName', sprintf( ...
                        'Selected rank %d baseline', ...
                        rankIndex));
                else
                    plot( ...
                        app.UIAxes, ...
                        app.wn(valid), ...
                        candidate.baseline(valid), ...
                        'LineWidth', 0.9, ...
                        'DisplayName', sprintf( ...
                        'Rank %d baseline', ...
                        rankIndex));
                end
            end
            
            hold(app.UIAxes, 'off');
            
            xlabel(app.UIAxes, ...
                'Raman shift (cm^{-1})');
            
            ylabel(app.UIAxes, ...
                'Intensity');
            
            title(app.UIAxes, { ...
                sprintf( ...
                'Quick View — raw spectrum and candidate baselines'), ...
                app.getQuickViewParameterText( ...
                selectedCandidate.params)}, ...
                'Interpreter', 'none');
            
            legend(app.UIAxes, ...
                'Location', 'best');
            
            box(app.UIAxes, 'on');
            grid(app.UIAxes, 'off');
            
            %% Axis 2: corrected spectra
            cla(app.UIAxes2);
            hold(app.UIAxes2, 'on');
            
            for rankIndex = displayRanks
                candidate = ...
                    app.quickViewCandidates(rankIndex);
                
                if rankIndex == selectedRank
                    plot( ...
                        app.UIAxes2, ...
                        app.wn, ...
                        candidate.corrected, ...
                        'r', ...
                        'LineWidth', 2.2, ...
                        'DisplayName', sprintf( ...
                        'Selected rank %d', ...
                        rankIndex));
                else
                    plot( ...
                        app.UIAxes2, ...
                        app.wn, ...
                        candidate.corrected, ...
                        'LineWidth', 0.9, ...
                        'DisplayName', sprintf( ...
                        'Rank %d', ...
                        rankIndex));
                end
            end
            
            yline( ...
                app.UIAxes2, ...
                0, ...
                '--', ...
                'Zero', ...
                'HandleVisibility', 'off');
            
            hold(app.UIAxes2, 'off');
            
            xlabel(app.UIAxes2, ...
                'Raman shift (cm^{-1})');
            
            ylabel(app.UIAxes2, ...
                'Corrected intensity');
            
            title(app.UIAxes2, sprintf( ...
                ['Corrected spectra — selected rank %d | ' ...
                'score %.4f | %s'], ...
                selectedCandidate.Rank, ...
                selectedCandidate.Score, ...
                char(selectedCandidate.Status)));
            
            legend(app.UIAxes2, ...
                'Location', 'best');
            
            box(app.UIAxes2, 'on');
            grid(app.UIAxes2, 'off');
            
            xl = app.getDefinitionXLim();
            xlim(app.UIAxes, xl);
            xlim(app.UIAxes2, xl);
            
            app.showQuickViewMetricValues( ...
                selectedCandidate);
        end
        
        
        function showQuickViewMetricValues(app, candidate)
            % Update existing metric EditFields for selected candidate.
            
            if isfinite(candidate.RMS)
                app.ResidualRMSEditField.Value = ...
                    sprintf('%.5g', candidate.RMS);
            else
                app.ResidualRMSEditField.Value = 'N/A';
            end
            
            if isfinite(candidate.Curvature)
                app.CurvatureEditField.Value = ...
                    sprintf('%.5g', candidate.Curvature);
            else
                app.CurvatureEditField.Value = 'N/A';
            end
            
            if isfinite(candidate.NegRatio)
                app.NegativeresidualratioEditField.Value = ...
                    sprintf('%.2f %%', ...
                    100 * candidate.NegRatio);
            else
                app.NegativeresidualratioEditField.Value = ...
                    'N/A';
            end
            
            if isfinite(candidate.PeakPres)
                app.PeakpreservationEditField.Value = ...
                    sprintf('%.2f %%', ...
                    100 * candidate.PeakPres);
            else
                app.PeakpreservationEditField.Value = ...
                    'N/A';
            end
            
            app.ResidualRMSEditField.Tooltip = sprintf( ...
                'Composite score: %.4f\nRank: %d\nStatus: %s', ...
                candidate.Score, ...
                candidate.Rank, ...
                char(candidate.Status));
            
            app.CurvatureEditField.Tooltip = ...
                app.getQuickViewParameterText( ...
                candidate.params);
            
            app.NegativeresidualratioEditField.Tooltip = ...
                sprintf( ...
                ['Candidate rank %d.\n' ...
                'Values above 15%% receive a ranking penalty.'], ...
                candidate.Rank);
            
            app.PeakpreservationEditField.Tooltip = ...
                sprintf( ...
                ['Candidate rank %d.\n' ...
                'Target acceptance interval: 80–120%%.'], ...
                candidate.Rank);
        end
        
        
        function applySelectedQuickViewParameters(app)
            % Copy selected candidate parameters into baselineParams.
            
            if ~app.quickViewActive || ...
                    isempty(app.quickViewSelectedRank)
                error('No Quick View parameter set is selected.');
            end
            
            candidate = app.quickViewCandidates( ...
                app.quickViewSelectedRank);
            
            method = char(app.quickViewMethod);
            
            app.baselineParams.method = method;
            
            switch method
                case 'ALS'
                    app.baselineParams.ALS = ...
                        candidate.params;
                    
                case 'ArPLS'
                    app.baselineParams.ArPLS = ...
                        candidate.params;
                    
                case 'AirPLS'
                    app.baselineParams.AirPLS = ...
                        candidate.params;
                    
                otherwise
                    error('Unsupported Quick View method.');
            end
            
            app.MethodsDropDown.Value = method;
        end
        
        
        function exitQuickViewMode(app, restoreDisplay)
            % Leave parameter-exploration mode and restore original Tree.
            
            if nargin < 2
                restoreDisplay = true;
            end
            
            app.quickViewActive = false;
            app.quickViewMethod = "";
            app.quickViewRaw = [];
            app.quickViewCandidates = struct([]);
            app.quickViewSelectedRank = [];
            
            switch app.mode
                case 1
                    app.populateSingleTree();
                    
                    if restoreDisplay
                        if ~isempty(app.correctedSpectra)
                            app.plotCorrectedSingle( ...
                                app.selectedIndex);
                        else
                            app.plotSingleSpectrum( ...
                                app.selectedIndex);
                        end
                    end
                    
                case 2
                    app.populateScanTree();
                    
                    if restoreDisplay
                        if ~isempty(app.correctedCube)
                            app.plotCorrectedScanMean();
                        else
                            app.plotScanMeanSpectrum();
                        end
                    end
            end
            
            if restoreDisplay
                if isempty(app.metricTable)
                    app.clearMetricDisplays();
                else
                    switch app.mode
                        case 1
                            if istable(app.metricTable)
                                app.showMetricForSingleSpectrum( ...
                                    app.selectedIndex);
                                app.plotMetricHistograms();
                            end
                            
                        case 2
                            if isstruct(app.metricTable)
                                app.showScanMetricSummary();
                                app.plotMetricMaps();
                            end
                    end
                end
            end
        end
        
        
        % Function 1
        function initializeDefaultSpectralDefinition(app)
            app.spectralDef = struct();
            app.spectralDef.roiRanges = [min(app.wn), max(app.wn)];
            app.spectralDef.silentRanges = [];
            app.spectralDef.peakRanges = [];
            app.spectralDef.excludeRanges = [];
            app.spectralDef.roiMask = true(size(app.wn(:)));
            app.spectralDef.silentMask = false(size(app.wn(:)));
            app.spectralDef.peakMask = false(size(app.wn(:)));
            app.spectralDef.excludeMask = false(size(app.wn(:)));
            % Backward-compatible names
            app.spectralDef.ignoredRanges = [];
            app.spectralDef.backgroundRanges = [];
            app.spectralDef.fitMask = app.spectralDef.roiMask;
            app.spectralDef.evalMask = app.spectralDef.silentMask;
        end
        
        % Sub function
        function normalizeSpectralDefinition(app)
            if isempty(app.spectralDef)
                app.initializeDefaultSpectralDefinition();
                return
            end
            
            if ~isfield(app.spectralDef, 'roiRanges') || isempty(app.spectralDef.roiRanges)
                app.spectralDef.roiRanges = [min(app.wn), max(app.wn)];
            end
            
            if ~isfield(app.spectralDef, 'silentRanges')
                app.spectralDef.silentRanges = [];
            end
            
            if ~isfield(app.spectralDef, 'peakRanges')
                app.spectralDef.peakRanges = [];
            end
            
            if ~isfield(app.spectralDef, 'excludeRanges')
                app.spectralDef.excludeRanges = [];
            end
            
            app.updateSpectralMasks();
            
            msgbox(sprintf([ ...
                'Baseline received spectral definition.\n\n' ...
                'ROI ranges:\n%s\n\n' ...
                'Exclude ranges:\n%s\n\n' ...
                'ROI points: %d / %d\n' ...
                'True ROI WN range: %.2f - %.2f'], ...
                mat2str(app.spectralDef.roiRanges), ...
                mat2str(app.spectralDef.excludeRanges), ...
                nnz(app.spectralDef.roiMask), ...
                numel(app.wn), ...
                min(app.wn(app.spectralDef.roiMask)), ...
                max(app.wn(app.spectralDef.roiMask))), ...
                'Baseline spectral definition check');
        end
        
        
        % Function 2
        function initializeDefaultBaselineParams(app)
            app.baselineParams.method = 'ArPLS';
            app.baselineParams.ALS.lam = 1e5;
            app.baselineParams.ALS.p = 0.001;
            app.baselineParams.ALS.niter = 20;
            app.baselineParams.ALS.tol = 1e-6;
            app.baselineParams.ArPLS.lam = 1e5;
            app.baselineParams.ArPLS.ratio = 1e-6;
            app.baselineParams.ArPLS.niter = 50;
            app.baselineParams.AirPLS.lam = 1e5;
            app.baselineParams.AirPLS.niter = 50;
            app.baselineParams.AirPLS.ratio = 1e-3;
            app.baselineParams.PeakStripping.maxWindow = [];
            app.baselineParams.PeakStripping.windowStep = 2;
            app.baselineParams.PeakStripping.paddingFactor = 0.5;
            app.baselineParams.PeakStripping.polyOrder = 0;
            app.MethodsDropDown.Value = 'ArPLS';
        end
        
        
        % Function 3
        function clearMetricDisplays(app)
            app.ResidualRMSEditField.Value = '';
            app.CurvatureEditField.Value = '';
            app.NegativeresidualratioEditField.Value = '';
            app.PeakpreservationEditField.Value = '';
            cla(app.UIAxes3_5);
            title(app.UIAxes3_5, 'Residual RMS');
            cla(app.UIAxes3_2);
            title(app.UIAxes3_2, 'Curvature');
            cla(app.UIAxes3);
            title(app.UIAxes3, 'Negative residual ratio');
            cla(app.UIAxes3_4);
            title(app.UIAxes3_4, 'Peak preservation');
        end
        
        % Function 4
        function populateSingleTree(app)
            delete(app.Tree.Children);
            root = uitreenode(app.Tree);
            root.Text = sprintf('Single spectra (%d)', size(app.spectra,2));
            root.NodeData = [];
            for k = 1:size(app.spectra,2)
                node = uitreenode(root);
                node.Text = app.names(k);
                node.NodeData = k;
            end
            expand(root);
        end
        
        % Function 5
        function populateScanTree(app)
            delete(app.Tree.Children);
            root = uitreenode(app.Tree);
            root.Text = sprintf('Scan cube: %d × %d × %d', ...
                size(app.cube,1), size(app.cube,2), size(app.cube,3));
            root.NodeData = [];
            uitreenode(root, 'Text', sprintf('Y pixels: %d', size(app.cube,1)));
            uitreenode(root, 'Text', sprintf('X pixels: %d', size(app.cube,2)));
            uitreenode(root, 'Text', sprintf('Wavenumber points: %d', size(app.cube,3)));
            expand(root);
        end
        
        
        % Function 6
        function plotSingleSpectrum(app, idx)
            if isempty(app.spectra)
                return
            end
            app.selectedIndex = idx;
            raw = app.spectra(:,idx);
            cla(app.UIAxes);
            plot(app.UIAxes, app.wn, raw, 'k', 'LineWidth', 1);
            xlabel(app.UIAxes, 'Wavenumber');
            ylabel(app.UIAxes, 'Intensity');
            title(app.UIAxes, sprintf('Raw spectrum: %s', app.names(idx)));
            app.applyDefinitionXLim();
            cla(app.UIAxes2);
            title(app.UIAxes2, 'Corrected spectrum');
            xlabel(app.UIAxes2, 'Wavenumber');
            ylabel(app.UIAxes2, 'Corrected intensity');
            app.applyDefinitionXLim();
        end
        
        
        % Function 7
        function plotScanMeanSpectrum(app)
            spectraMatrix = app.cubeToSpectraMatrixLocal(app.cube);
            meanSpectrum = mean(spectraMatrix, 2, 'omitnan');
            cla(app.UIAxes);
            plot(app.UIAxes, app.wn, meanSpectrum, 'k', 'LineWidth', 1);
            xlabel(app.UIAxes, 'Wavenumber');
            ylabel(app.UIAxes, 'Mean intensity');
            title(app.UIAxes, 'Mean scan spectrum');
            app.applyDefinitionXLim();
            cla(app.UIAxes2);
            title(app.UIAxes2, 'Corrected mean spectrum');
            xlabel(app.UIAxes2, 'Wavenumber');
            ylabel(app.UIAxes2, 'Corrected intensity');
            app.applyDefinitionXLim();
        end
        
        % Function 8
        function spectraMatrix = cubeToSpectraMatrixLocal(app, cubeData)
            ny = size(cubeData, 1);
            nx = size(cubeData, 2);
            nWn = size(cubeData, 3);
            spectraMatrix = reshape( ...
                permute(cubeData, [3 2 1]), ...
                nWn, nx * ny);
        end
        
        % Function 9:
        function [base, corrected] = runBaselineOneSpectrum(app, y)
            method = app.baselineParams.method;
            y = y(:);
            n = numel(y);
            
            base = nan(n,1);
            corrected = y;
            
            if isempty(app.spectralDef) || ...
                    ~isfield(app.spectralDef, 'roiMask') || ...
                    numel(app.spectralDef.roiMask) ~= n || ...
                    ~any(app.spectralDef.roiMask)
                
                processMask = true(n,1);
            else
                processMask = app.spectralDef.roiMask(:);
            end
            
            if ~isempty(app.spectralDef) && ...
                    isfield(app.spectralDef, 'excludeMask') && ...
                    numel(app.spectralDef.excludeMask) == n
                
                processMask = processMask & ~app.spectralDef.excludeMask(:);
            end
            
            segments = app.maskToSegments(processMask);
            
            for s = 1:size(segments,1)
                idx = segments(s,1):segments(s,2);
                
                if numel(idx) < 3
                    continue
                end
                
                yROI = y(idx);
                
                finiteMask = isfinite(yROI);
                if nnz(finiteMask) < 3
                    base(idx) = NaN;
                    corrected(idx) = yROI;
                    continue
                end
                
                switch method
                    case 'ALS'
                        alsParam = app.baselineParams.ALS;
                        [baseROI, correctedROI] = baseline_als_raman( ...
                            yROI, alsParam.lam, alsParam.p, alsParam.niter, alsParam.tol);
                        
                    case 'ArPLS'
                        p = app.baselineParams.ArPLS;
                        [baseROI, correctedROI] = baseline_arpls_raman( ...
                            yROI, p.lam, p.ratio, p.niter);
                        
                    case 'AirPLS'
                        p = app.baselineParams.AirPLS;
                        [baseROI, correctedROI] = baseline_airpls_raman( ...
                            yROI, p.lam, p.niter, p.ratio);
                        
                    case 'Peak stripping'
                        [baseROI, correctedROI] = function_baseline_peakstripping(yROI);
                end
                
                base(idx) = baseROI(:);
                corrected(idx) = correctedROI(:);
            end
            
            corrected(isnan(base)) = y(isnan(base));
        end
        
        
        % Function 10:
        function runBaselineSingle(app)
            n = size(app.spectra, 2);
            nWn = numel(app.wn);
            
            app.baselineSpectra = nan(nWn, n);
            app.correctedSpectra = zeros(nWn, n);
            
            d = uiprogressdlg(app.UIFigure, ...
                'Title', 'Baseline correction', ...
                'Message', 'Correcting single spectra...', ...
                'Indeterminate', 'off', ...
                'Value', 0);
            
            for k = 1:n
                y = app.spectra(:,k);
                
                [base, corrected] = app.runBaselineOneSpectrum(y);
                
                app.baselineSpectra(:,k) = base;
                app.correctedSpectra(:,k) = corrected;
                
                d.Value = k / n;
                d.Message = sprintf('Correcting spectrum %d / %d', k, n);
                drawnow limitrate
            end
            
            close(d);
            
            app.plotCorrectedSingle(app.selectedIndex);
            app.computeBaselineMetrics();
        end
        
        % Function 11:
        function runBaselineScan(app)
            [ny, nx, nWn] = size(app.cube);
            
            app.baselineCube = nan(ny, nx, nWn);
            app.correctedCube = zeros(ny, nx, nWn);
            
            total = ny * nx;
            count = 0;
            
            d = uiprogressdlg(app.UIFigure, ...
                'Title', 'Baseline correction', ...
                'Message', 'Correcting scan spectra...', ...
                'Indeterminate', 'off', ...
                'Value', 0);
            
            for iy = 1:ny
                for ix = 1:nx
                    y = squeeze(app.cube(iy, ix, :));
                    
                    [base, corrected] = app.runBaselineOneSpectrum(y);
                    
                    app.baselineCube(iy, ix, :) = base;
                    app.correctedCube(iy, ix, :) = corrected;
                    
                    count = count + 1;
                    d.Value = count / total;
                    d.Message = sprintf('Correcting pixel %d / %d', count, total);
                    drawnow limitrate
                end
            end
            
            close(d);
            
            app.plotCorrectedScanMean();
            app.computeBaselineMetrics();
        end
        
        % Function 12:
        function plotCorrectedSingle(app, idx)
            raw = app.spectra(:,idx);
            base = app.baselineSpectra(:,idx);
            corrected = app.correctedSpectra(:,idx);
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, app.wn, raw, 'k', 'LineWidth', 0.8);
            plot(app.UIAxes, app.wn, base, 'r', 'LineWidth', 1.2);
            hold(app.UIAxes, 'off');
            xlabel(app.UIAxes, 'Wavenumber');
            ylabel(app.UIAxes, 'Intensity');
            title(app.UIAxes, sprintf('Raw + baseline: %s', app.names(idx)));
            legend(app.UIAxes, {'Raw', 'Baseline'}, 'Location', 'best');
            grid(app.UIAxes, 'off');
            cla(app.UIAxes2);
            plot(app.UIAxes2, app.wn, corrected, 'b', 'LineWidth', 1);
            xlabel(app.UIAxes2, 'Wavenumber');
            ylabel(app.UIAxes2, 'Corrected intensity');
            title(app.UIAxes2, sprintf('Corrected: %s', app.names(idx)));
            grid(app.UIAxes2, 'off');
            app.applyDefinitionXLim();
        end
        % Function 13:
        function plotCorrectedScanMean(app)
            rawMatrix = app.cubeToSpectraMatrixLocal(app.cube);
            correctedMatrix = app.cubeToSpectraMatrixLocal(app.correctedCube);
            baselineMatrix = app.cubeToSpectraMatrixLocal(app.baselineCube);
            
            rawMean = mean(rawMatrix, 2, 'omitnan');
            baseMean = mean(baselineMatrix, 2, 'omitnan');
            corrMean = mean(correctedMatrix, 2, 'omitnan');
            
            allNaNBase = all(isnan(baselineMatrix), 2);
            baseMean(allNaNBase) = NaN;
            
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, app.wn, rawMean, 'k', 'LineWidth', 0.8);
            plot(app.UIAxes, app.wn, baseMean, 'r', 'LineWidth', 1.2);
            hold(app.UIAxes, 'off');
            
            xlabel(app.UIAxes, 'Wavenumber');
            ylabel(app.UIAxes, 'Mean intensity');
            title(app.UIAxes, 'Mean raw + mean baseline');
            legend(app.UIAxes, {'Mean raw', 'Mean baseline'}, 'Location', 'best');
            grid(app.UIAxes, 'off');
            
            cla(app.UIAxes2);
            plot(app.UIAxes2, app.wn, corrMean, 'b', 'LineWidth', 1);
            xlabel(app.UIAxes2, 'Wavenumber');
            ylabel(app.UIAxes2, 'Mean corrected intensity');
            title(app.UIAxes2, 'Mean corrected scan spectrum');
            grid(app.UIAxes2, 'off');
            
            app.applyDefinitionXLim();
        end
        
        % Function 14:
        function computeBaselineMetrics(app)
            if app.mode == 1
                n = size(app.spectra, 2);
                RMS = zeros(n,1);
                Curvature = zeros(n,1);
                NegRatio = zeros(n,1);
                PeakPres = nan(n,1);
                for k = 1:n
                    raw = app.spectra(:,k);
                    base = app.baselineSpectra(:,k);
                    corr = app.correctedSpectra(:,k);
                    [RMS(k), Curvature(k), NegRatio(k), PeakPres(k)] = ...
                        app.computeMetricsOneSpectrum(raw, base, corr);
                end
                app.metricTable = table(RMS, Curvature, NegRatio, PeakPres);
                app.showMetricForSingleSpectrum(app.selectedIndex);
                app.plotMetricHistograms();
            elseif app.mode == 2
                [ny, nx, ~] = size(app.cube);
                RMSMap = zeros(ny,nx);
                CurvMap = zeros(ny,nx);
                NegMap = zeros(ny,nx);
                PeakMap = nan(ny,nx);
                for iy = 1:ny
                    for ix = 1:nx
                        raw = squeeze(app.cube(iy,ix,:));
                        base = squeeze(app.baselineCube(iy,ix,:));
                        corr = squeeze(app.correctedCube(iy,ix,:));
                        [RMSMap(iy,ix), CurvMap(iy,ix), NegMap(iy,ix), PeakMap(iy,ix)] = ...
                            app.computeMetricsOneSpectrum(raw, base, corr);
                    end
                end
                app.metricTable = struct();
                app.metricTable.RMSMap = RMSMap;
                app.metricTable.CurvatureMap = CurvMap;
                app.metricTable.NegativeRatioMap = NegMap;
                app.metricTable.PeakPreservationMap = PeakMap;
                app.showScanMetricSummary();
                app.plotMetricMaps();
            end
            drawnow;
        end
        
        % Function 15:
        function [rmsVal, curvatureVal, negRatio, peakPres] = computeMetricsOneSpectrum(app, raw, base, corr)
            raw = raw(:);
            base = base(:);
            corr = corr(:);
            n = numel(raw);
            validBase = ~isnan(base);
            if isfield(app.spectralDef, 'silentMask') && any(app.spectralDef.silentMask)
                evalMask = app.spectralDef.silentMask(:) & validBase;
            elseif isfield(app.spectralDef, 'roiMask') && any(app.spectralDef.roiMask)
                evalMask = app.spectralDef.roiMask(:) & validBase;
            else
                evalMask = validBase;
            end
            if isfield(app.spectralDef, 'excludeMask') && numel(app.spectralDef.excludeMask) == n
                evalMask = evalMask & ~app.spectralDef.excludeMask(:);
            end
            if ~any(evalMask)
                rmsVal = NaN;
            else
                r = corr(evalMask);
                rmsVal = sqrt(mean(r.^2, 'omitnan'));
            end
            if nnz(validBase) < 3
                curvatureVal = NaN;
            else
                b = base;
                b(~validBase) = NaN;
                curvVals = [];
                if isfield(app.spectralDef, 'roiRanges') && ~isempty(app.spectralDef.roiRanges)
                    for rr = 1:size(app.spectralDef.roiRanges,1)
                        lo = min(app.spectralDef.roiRanges(rr,1), app.spectralDef.roiRanges(rr,2));
                        hi = max(app.spectralDef.roiRanges(rr,1), app.spectralDef.roiRanges(rr,2));
                        idx = find(app.wn >= lo & app.wn <= hi & validBase);
                        if numel(idx) >= 3
                            bb = b(idx);
                            curvVals(end+1,1) = sum(diff(bb,2).^2, 'omitnan') / ...
                                max(sum(bb.^2, 'omitnan'), eps);
                        end
                    end
                else
                    bb = b(validBase);
                    curvVals = sum(diff(bb,2).^2, 'omitnan') / ...
                        max(sum(bb.^2, 'omitnan'), eps);
                end
                
                curvatureVal = mean(curvVals, 'omitnan');
            end
            validMask = validBase;
            if isfield(app.spectralDef, 'excludeMask') && numel(app.spectralDef.excludeMask) == n
                validMask = validMask & ~app.spectralDef.excludeMask(:);
            end
            if ~any(validMask)
                negRatio = NaN;
            else
                negRatio = sum(corr(validMask) < 0) / max(nnz(validMask), 1);
            end
            if isfield(app.spectralDef, 'peakMask') && any(app.spectralDef.peakMask)
                peakMask = app.spectralDef.peakMask(:) & validBase;
                if any(peakMask)
                    rawPeakArea = trapz(app.wn(peakMask), raw(peakMask) - base(peakMask));
                    corrPeakArea = trapz(app.wn(peakMask), corr(peakMask));
                    if abs(rawPeakArea) < eps
                        peakPres = NaN;
                    else
                        peakPres = corrPeakArea / rawPeakArea;
                    end
                else
                    peakPres = NaN;
                end
            else
                peakPres = NaN;
            end
        end
        
        
        % Function 16:
        function showMetricForSingleSpectrum(app, idx)
            if isempty(app.metricTable)
                return
            end
            app.ResidualRMSEditField.Value = sprintf('%.4g', app.metricTable.RMS(idx));
            app.CurvatureEditField.Value = sprintf('%.4g', app.metricTable.Curvature(idx));
            app.NegativeresidualratioEditField.Value = sprintf('%.2f %%', ...
                100 * app.metricTable.NegRatio(idx));
            if isnan(app.metricTable.PeakPres(idx))
                app.PeakpreservationEditField.Value = 'N/A';
            else
                app.PeakpreservationEditField.Value = sprintf('%.2f %%', ...
                    100 * app.metricTable.PeakPres(idx));
            end
            app.setupMetricTooltips();
        end
        
        % Function 17:
        function plotMetricHistograms(app)
            cla(app.UIAxes3_5);
            histogram(app.UIAxes3_5, app.metricTable.RMS);
            title(app.UIAxes3_5, 'Residual RMS');
            xlabel(app.UIAxes3_5, 'RMS');
            ylabel(app.UIAxes3_5, 'Count');
            cla(app.UIAxes3_2);
            histogram(app.UIAxes3_2, app.metricTable.Curvature);
            title(app.UIAxes3_2, 'Curvature');
            xlabel(app.UIAxes3_2, 'Curvature');
            ylabel(app.UIAxes3_2, 'Count');
            cla(app.UIAxes3);
            histogram(app.UIAxes3, 100 * app.metricTable.NegRatio);
            title(app.UIAxes3, 'Negative residual ratio');
            xlabel(app.UIAxes3, 'Negative points (%)');
            ylabel(app.UIAxes3, 'Count');
            cla(app.UIAxes3_4);
            validPeak = app.metricTable.PeakPres(~isnan(app.metricTable.PeakPres));
            if isempty(validPeak)
                text(app.UIAxes3_4, 0.5, 0.5, ...
                    'N/A: no peak region defined', ...
                    'HorizontalAlignment', 'center');
                xlim(app.UIAxes3_4, [0 1]);
                ylim(app.UIAxes3_4, [0 1]);
                xlabel(app.UIAxes3_4, '');
                ylabel(app.UIAxes3_4, '');
            else
                histogram(app.UIAxes3_4, 100 * validPeak);
                xlabel(app.UIAxes3_4, 'Peak preservation (%)');
                ylabel(app.UIAxes3_4, 'Count');
            end
            title(app.UIAxes3_4, 'Peak preservation');
        end
        
        % Function 18:
        function plotMetricMaps(app)
            cla(app.UIAxes3_5);
            imagesc(app.UIAxes3_5, app.metricTable.RMSMap);
            axis(app.UIAxes3_5, 'image');
            colorbar(app.UIAxes3_5);
            title(app.UIAxes3_5, 'Residual RMS map');
            xlabel(app.UIAxes3_5, 'X pixel');
            ylabel(app.UIAxes3_5, 'Y pixel');
            
            cla(app.UIAxes3_2);
            imagesc(app.UIAxes3_2, app.metricTable.CurvatureMap);
            axis(app.UIAxes3_2, 'image');
            colorbar(app.UIAxes3_2);
            title(app.UIAxes3_2, 'Curvature map');
            xlabel(app.UIAxes3_2, 'X pixel');
            ylabel(app.UIAxes3_2, 'Y pixel');
            
            cla(app.UIAxes3);
            imagesc(app.UIAxes3, 100 * app.metricTable.NegativeRatioMap);
            axis(app.UIAxes3, 'image');
            colorbar(app.UIAxes3);
            title(app.UIAxes3, 'Negative residual ratio map (%)');
            xlabel(app.UIAxes3, 'X pixel');
            ylabel(app.UIAxes3, 'Y pixel');
            
            cla(app.UIAxes3_4);
            imagesc(app.UIAxes3_4, 100 * app.metricTable.PeakPreservationMap);
            axis(app.UIAxes3_4, 'image');
            colorbar(app.UIAxes3_4);
            title(app.UIAxes3_4, 'Peak preservation map (%)');
            xlabel(app.UIAxes3_4, 'X pixel');
            ylabel(app.UIAxes3_4, 'Y pixel');
        end
        % Function 19:
        function updateSpectralMasks(app)
            wn = app.wn(:);
            
            roiMask = false(size(wn));
            silentMask = false(size(wn));
            peakMask = false(size(wn));
            excludeMask = false(size(wn));
            
            if isfield(app.spectralDef, 'roiRanges') && ~isempty(app.spectralDef.roiRanges)
                for k = 1:size(app.spectralDef.roiRanges,1)
                    lo = min(app.spectralDef.roiRanges(k,1), app.spectralDef.roiRanges(k,2));
                    hi = max(app.spectralDef.roiRanges(k,1), app.spectralDef.roiRanges(k,2));
                    roiMask = roiMask | (wn >= lo & wn <= hi);
                end
            else
                roiMask = true(size(wn));
            end
            
            if isfield(app.spectralDef, 'silentRanges') && ~isempty(app.spectralDef.silentRanges)
                for k = 1:size(app.spectralDef.silentRanges,1)
                    lo = min(app.spectralDef.silentRanges(k,1), app.spectralDef.silentRanges(k,2));
                    hi = max(app.spectralDef.silentRanges(k,1), app.spectralDef.silentRanges(k,2));
                    silentMask = silentMask | (wn >= lo & wn <= hi);
                end
            end
            
            if isfield(app.spectralDef, 'peakRanges') && ~isempty(app.spectralDef.peakRanges)
                for k = 1:size(app.spectralDef.peakRanges,1)
                    lo = min(app.spectralDef.peakRanges(k,1), app.spectralDef.peakRanges(k,2));
                    hi = max(app.spectralDef.peakRanges(k,1), app.spectralDef.peakRanges(k,2));
                    peakMask = peakMask | (wn >= lo & wn <= hi);
                end
            end
            
            if isfield(app.spectralDef, 'excludeRanges') && ~isempty(app.spectralDef.excludeRanges)
                for k = 1:size(app.spectralDef.excludeRanges,1)
                    lo = min(app.spectralDef.excludeRanges(k,1), app.spectralDef.excludeRanges(k,2));
                    hi = max(app.spectralDef.excludeRanges(k,1), app.spectralDef.excludeRanges(k,2));
                    excludeMask = excludeMask | (wn >= lo & wn <= hi);
                end
            end
            
            app.spectralDef.roiMask = roiMask & ~excludeMask;
            app.spectralDef.silentMask = silentMask & ~excludeMask;
            app.spectralDef.peakMask = peakMask & ~excludeMask;
            app.spectralDef.excludeMask = excludeMask;
            
            app.spectralDef.fitMask = app.spectralDef.roiMask;
            app.spectralDef.evalMask = app.spectralDef.silentMask;
            app.spectralDef.backgroundRanges = app.spectralDef.silentRanges;
            app.spectralDef.ignoredRanges = app.spectralDef.excludeRanges;
        end
        
        
        function segments = maskToSegments(app, mask)
            mask = mask(:);
            
            d = diff([false; mask; false]);
            startIdx = find(d == 1);
            endIdx = find(d == -1) - 1;
            
            segments = [startIdx, endIdx];
        end
        
        % Function 21:
        function z = safeZscore(app, x)
            x = x(:);
            mu = mean(x, 'omitnan');
            sg = std(x, 'omitnan');
            if sg < eps || isnan(sg)
                z = zeros(size(x));
            else
                z = (x - mu) ./ sg;
            end
        end
        
        
        % Function 22:
        function markWorstSpectraInTree(app, worstIdx)
            if isempty(app.Tree.Children)
                return
            end
            nodes = app.Tree.Children.Children;
            for k = 1:numel(nodes)
                nodes(k).Text = app.names(k);
            end
            for k = 1:numel(worstIdx)
                idx = worstIdx(k);
                nodes(idx).Text = ">>> " + app.names(idx) + " [WORST]";
            end
        end
        
        
        function setupMetricTooltips(app)
            rmsTip = sprintf([ ...
                'Residual RMS = residual size after baseline correction.\n' ...
                'Calculated mainly in silent/no-peak region.\n\n' ...
                'Good: low compared with other spectra/pixels.\n' ...
                'Warning: much higher than dataset median.\n\n' ...
                'High RMS means remaining baseline, noise, or under/over-correction.']);
            curvTip = sprintf([ ...
                'Curvature = how wavy the fitted baseline is.\n\n' ...
                'Good: smooth, low-to-moderate value.\n' ...
                'Warning: very high value may mean baseline is following real Raman peaks.']);
            negTip = sprintf([ ...
                'Negative residual ratio = percentage of corrected points below zero.\n\n' ...
                '0-5%%: usually acceptable.\n' ...
                '5-15%%: check visually.\n' ...
                '>15%%: possible over-correction.']);
            peakTip = sprintf([ ...
                'Peak preservation = corrected peak area / original peak area.\n\n' ...
                '80-120%%: usually acceptable.\n' ...
                '<80%%: possible peak loss.\n' ...
                '>120%%: possible remaining baseline or inflated peak.\n' ...
                'N/A: no peak region defined.']);
            if isprop(app.ResidualRMSEditFieldLabel, 'Tooltip')
                app.ResidualRMSEditFieldLabel.Tooltip = rmsTip;
            end
            if isprop(app.ResidualRMSEditField, 'Tooltip')
                app.ResidualRMSEditField.Tooltip = rmsTip;
            end
            if isprop(app.CurvatureEditFieldLabel, 'Tooltip')
                app.CurvatureEditFieldLabel.Tooltip = curvTip;
            end
            if isprop(app.CurvatureEditField, 'Tooltip')
                app.CurvatureEditField.Tooltip = curvTip;
            end
            if isprop(app.NegativeresidualratioLabel, 'Tooltip')
                app.NegativeresidualratioLabel.Tooltip = negTip;
            end
            if isprop(app.NegativeresidualratioEditField, 'Tooltip')
                app.NegativeresidualratioEditField.Tooltip = negTip;
            end
            if isprop(app.PeakpreservationLabel, 'Tooltip')
                app.PeakpreservationLabel.Tooltip = peakTip;
            end
            if isprop(app.PeakpreservationEditField, 'Tooltip')
                app.PeakpreservationEditField.Tooltip = peakTip;
            end
        end
        
        
        function showScanMetricSummary(app)
            if isempty(app.metricTable) || ~isstruct(app.metricTable)
                return
            end
            rmsVals = app.metricTable.RMSMap(:);
            curvVals = app.metricTable.CurvatureMap(:);
            negVals = app.metricTable.NegativeRatioMap(:);
            peakVals = app.metricTable.PeakPreservationMap(:);
            peakVals = peakVals(~isnan(peakVals));
            app.ResidualRMSEditField.Value = sprintf('median %.4g | max %.4g', ...
                median(rmsVals, 'omitnan'), max(rmsVals, [], 'omitnan'));
            app.CurvatureEditField.Value = sprintf('median %.4g | max %.4g', ...
                median(curvVals, 'omitnan'), max(curvVals, [], 'omitnan'));
            app.NegativeresidualratioEditField.Value = sprintf('median %.2f %% | max %.2f %%', ...
                100 * median(negVals, 'omitnan'), 100 * max(negVals, [], 'omitnan'));
            if isempty(peakVals)
                app.PeakpreservationEditField.Value = 'N/A';
            else
                app.PeakpreservationEditField.Value = sprintf('median %.2f %%', ...
                    100 * median(peakVals, 'omitnan'));
            end
            app.setupMetricTooltips();
        end
        
        
        function setupMetricPlotPanelTooltips(app)
            if isprop(app.Panel_2, 'Tooltip')
                app.Panel_2.Tooltip = sprintf([ ...
                    'Metric plots: Residual RMS and Curvature.\n\n' ...
                    'Residual RMS:\n' ...
                    '- Lower is better.\n' ...
                    '- Right-side outliers or bright pixels need review.\n\n' ...
                    'Curvature:\n' ...
                    '- High value means the baseline is too wavy.\n' ...
                    '- High curvature may indicate peak distortion.']);
            end
            if isprop(app.Panel_3, 'Tooltip')
                app.Panel_3.Tooltip = sprintf([ ...
                    'Negative residual ratio.\n\n' ...
                    '0-5%%: usually acceptable.\n' ...
                    '5-15%%: check visually.\n' ...
                    '>15%%: possible over-correction.\n\n' ...
                    'High value means many corrected points are below zero.']);
            end
            if isprop(app.Panel_4, 'Tooltip')
                app.Panel_4.Tooltip = sprintf([ ...
                    'Peak preservation.\n\n' ...
                    '80-120%%: usually acceptable.\n' ...
                    '<80%%: possible peak loss.\n' ...
                    '>120%%: possible remaining baseline or inflated peak.\n\n' ...
                    'N/A means no peak region was defined.']);
            end
        end
        
        
        function xl = getDefinitionXLim(app)
            if ~isempty(app.spectralDef) && isfield(app.spectralDef,'roiMask') && numel(app.spectralDef.roiMask)==numel(app.wn) && any(app.spectralDef.roiMask)
                x = app.wn(app.spectralDef.roiMask);
                xl = [min(x),max(x)];
            elseif ~isempty(app.spectralDef) && isfield(app.spectralDef,'roiRanges') && ~isempty(app.spectralDef.roiRanges)
                xl = [min(app.spectralDef.roiRanges(:)),max(app.spectralDef.roiRanges(:))];
            else
                xl = [min(app.wn),max(app.wn)];
            end
        end
        
        function applyDefinitionXLim(app)
            xl = app.getDefinitionXLim();
            xlim(app.UIAxes,xl);
            xlim(app.UIAxes2,xl);
            grid(app.UIAxes,'off');
            grid(app.UIAxes2,'off');
        end
        
        
    end
    
    
    methods (Access = public)
        
        
        % Function 1
        function receiveData(app, mainApp, mode, wn, spectra, cube)
            app.mainApp = mainApp;
            app.mode = mode;
            app.wn = wn(:);
            app.spectra = spectra;
            app.cube = cube;
            app.correctedSpectra = [];
            app.correctedCube = [];
            app.baselineSpectra = [];
            app.baselineCube = [];
            app.selectedIndex = 1;
            app.quickViewActive = false;
            app.quickViewMethod = "";
            app.quickViewRaw = [];
            app.quickViewCandidates = struct([]);
            app.quickViewSelectedRank = [];
            if isprop(mainApp, 'spectralDef') && ~isempty(mainApp.spectralDef)
                app.spectralDef = mainApp.spectralDef;
                app.normalizeSpectralDefinition();
            else
                app.initializeDefaultSpectralDefinition();
            end
            app.initializeDefaultBaselineParams();
            delete(app.Tree.Children);
            switch app.mode
                case 1
                    n = size(app.spectra,2);
                    app.names = "Spectrum_" + compose("%03d", 1:n);
                    app.populateSingleTree();
                    app.plotSingleSpectrum(1);
                case 2
                    app.names = strings(0);
                    app.populateScanTree();
                    app.plotScanMeanSpectrum();
            end
            app.clearMetricDisplays();
            app.setupMetricTooltips();
            app.setupMetricPlotPanelTooltips();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: MethodsDropDown
        function MethodsDropDownValueChanged(app, event)
            value = app.MethodsDropDown.Value;
            
            if value == ""
                return
            end
            
            % Candidate results from the old method are no longer valid.
            if app.quickViewActive
                app.exitQuickViewMode(true);
            end
            
            switch value
                case 'ALS'
                    app.baselineParams.method = 'ALS';
                    
                case 'ArPLS'
                    app.baselineParams.method = 'ArPLS';
                    
                case 'AirPLS'
                    app.baselineParams.method = 'AirPLS';
                    
                case 'Peak stripping'
                    app.baselineParams.method = ...
                        'Peak stripping';
                    
                otherwise
                    uialert( ...
                        app.UIFigure, ...
                        ['This baseline method is not ' ...
                        'implemented yet.'], ...
                        'Method unavailable');
            end
            
        end

        % Button pushed function: BaselinesettingsButton
        function BaselinesettingsButtonPushed(app, event)
            if app.quickViewActive
                app.exitQuickViewMode(true);
            end
            method = app.baselineParams.method;
            switch method
                case 'ALS'
                    p = app.baselineParams.ALS;
                    msg = sprintf([ ...
                        'ALS parameters:\n\n' ...
                        'lambda: Larger = smoother baseline; smaller = more flexible baseline.\n' ...
                        'p: Smaller = baseline forced lower under peaks; larger = baseline closer to spectrum.\n' ...
                        'niter: More iterations = more stable but slower.\n' ...
                        'tol: Smaller = stricter convergence.\n']);
                    answer = inputdlg( ...
                        {'lambda:', 'p:', 'niter:', 'tol:', msg}, ...
                        'ALS baseline settings', ...
                        [1 60; 1 60; 1 60; 1 60; 8 60], ...
                        {num2str(p.lam), num2str(p.p), num2str(p.niter), num2str(p.tol), ''});
                    if isempty(answer), return; end
                    app.baselineParams.ALS.lam = str2double(answer{1});
                    app.baselineParams.ALS.p = str2double(answer{2});
                    app.baselineParams.ALS.niter = round(str2double(answer{3}));
                    app.baselineParams.ALS.tol = str2double(answer{4});
                case 'ArPLS'
                    p = app.baselineParams.ArPLS;
                    msg = sprintf([ ...
                        'arPLS parameters:\n\n' ...
                        'lambda: Larger = smoother baseline; smaller = more flexible baseline.\n' ...
                        'ratio: Larger = earlier stopping; smaller = stricter convergence.\n' ...
                        'niter: Maximum number of iterations.\n' ...
                        'arPLS usually does not need asymmetry parameter p.\n']);
                    answer = inputdlg( ...
                        {'lambda:', 'ratio:', 'niter:', msg}, ...
                        'arPLS baseline settings', ...
                        [1 60; 1 60; 1 60; 8 60], ...
                        {num2str(p.lam), num2str(p.ratio), num2str(p.niter), ''});
                    if isempty(answer), return; end
                    app.baselineParams.ArPLS.lam = str2double(answer{1});
                    app.baselineParams.ArPLS.ratio = str2double(answer{2});
                    app.baselineParams.ArPLS.niter = round(str2double(answer{3}));
                case 'AirPLS'
                    p = app.baselineParams.AirPLS;
                    msg = sprintf([ ...
                        'airPLS parameters:\n\n' ...
                        'lambda: Larger = smoother baseline; smaller = more flexible baseline.\n' ...
                        'niter: Maximum number of iterations.\n' ...
                        'ratio: Larger = earlier stopping; smaller = stricter correction.\n' ...
                        'airPLS can be more aggressive than arPLS.\n']);
                    answer = inputdlg( ...
                        {'lambda:', 'niter:', 'ratio:', msg}, ...
                        'airPLS baseline settings', ...
                        [1 60; 1 60; 1 60; 8 60], ...
                        {num2str(p.lam), num2str(p.niter), num2str(p.ratio), ''});
                    if isempty(answer), return; end
                    app.baselineParams.AirPLS.lam = str2double(answer{1});
                    app.baselineParams.AirPLS.niter = round(str2double(answer{2}));
                    app.baselineParams.AirPLS.ratio = str2double(answer{3});
                case 'Peak stripping'
                    p = app.baselineParams.PeakStripping;
                    msg = sprintf([ ...
                        'Peak stripping parameters:\n\n' ...
                        'This method uses iterative moving-average / zero-order SG clipping.\n' ...
                        'maxWindow: Larger = can remove wider peaks but may distort broad bands.\n' ...
                        'windowStep: Larger = faster but coarser search.\n' ...
                        'paddingFactor: Larger = less edge distortion but slower.\n' ...
                        'polyOrder: 0 is moving average; higher order is more flexible.\n\n' ...
                        'NOTE: Your current external function does not accept these parameters yet.\n' ...
                        'It will run with its internal default behavior unless you upgrade it.\n']);
                    if isempty(p.maxWindow)
                        maxWindowText = '';
                    else
                        maxWindowText = num2str(p.maxWindow);
                    end
                    answer = inputdlg( ...
                        {'maxWindow empty = auto/current function default:', ...
                        'windowStep:', ...
                        'paddingFactor:', ...
                        'polyOrder:', ...
                        msg}, ...
                        'Peak stripping settings', ...
                        [1 60; 1 60; 1 60; 1 60; 10 60], ...
                        {maxWindowText, num2str(p.windowStep), num2str(p.paddingFactor), num2str(p.polyOrder), ''});
                    if isempty(answer), return; end
                    if strlength(strtrim(string(answer{1}))) == 0
                        app.baselineParams.PeakStripping.maxWindow = [];
                    else
                        app.baselineParams.PeakStripping.maxWindow = round(str2double(answer{1}));
                    end
                    app.baselineParams.PeakStripping.windowStep = round(str2double(answer{2}));
                    app.baselineParams.PeakStripping.paddingFactor = str2double(answer{3});
                    app.baselineParams.PeakStripping.polyOrder = round(str2double(answer{4}));
            end
        end

        % Button pushed function: BaselinecorrectionButton
        function BaselinecorrectionButtonPushed(app, event)
            if isempty(app.spectralDef)
                uialert( ...
                    app.UIFigure, ...
                    ['Please run Data check and define Spectral ' ...
                    'definition first.'], ...
                    'Spectral definition required');
                return
            end
            
            if isempty(app.baselineParams.method)
                uialert( ...
                    app.UIFigure, ...
                    'Please select a baseline method first.', ...
                    'No method');
                return
            end
            
            try
                %% Apply selected Quick View candidate
                if app.quickViewActive
                    if isempty(app.quickViewSelectedRank)
                        uialert( ...
                            app.UIFigure, ...
                            ['Select one parameter candidate from ' ...
                            'the Quick View Tree first.'], ...
                            'No parameter set selected');
                        return
                    end
                    
                    selectedCandidate = ...
                        app.quickViewCandidates( ...
                        app.quickViewSelectedRank);
                    
                    selection = questdlg( ...
                        sprintf([ ...
                        'Apply Quick View rank %d?\n\n' ...
                        '%s\n\n' ...
                        'Composite score: %.4f\n' ...
                        'Status: %s'], ...
                        selectedCandidate.Rank, ...
                        app.getQuickViewParameterText( ...
                        selectedCandidate.params), ...
                        selectedCandidate.Score, ...
                        char(selectedCandidate.Status)), ...
                        'Apply Quick View parameters', ...
                        'Apply and run', ...
                        'Cancel', ...
                        'Apply and run');
                    
                    if ~strcmp(selection, 'Apply and run')
                        return
                    end
                    
                    app.applySelectedQuickViewParameters();
                    
                    % Restore original Tree before full correction.
                    app.exitQuickViewMode(false);
                end
                
                %% Run full dataset correction
                switch app.mode
                    case 1
                        app.runBaselineSingle();
                        
                    case 2
                        app.runBaselineScan();
                end
                
                uialert( ...
                    app.UIFigure, ...
                    'Baseline correction completed.', ...
                    'Done');
                
            catch ME
                uialert( ...
                    app.UIFigure, ...
                    sprintf( ...
                    'Baseline correction failed:\n\n%s', ...
                    ME.message), ...
                    'Baseline correction error');
            end
        end

        % Value changed function: ResidualRMSEditField
        function ResidualRMSEditFieldValueChanged(app, event)
            value = app.ResidualRMSEditField.Value;
            
        end

        % Value changed function: CurvatureEditField
        function CurvatureEditFieldValueChanged(app, event)
            value = app.CurvatureEditField.Value;
            
        end

        % Value changed function: NegativeresidualratioEditField
        function NegativeresidualratioEditFieldValueChanged(app, event)
            value = app.NegativeresidualratioEditField.Value;
            
        end

        % Value changed function: PeakpreservationEditField
        function PeakpreservationEditFieldValueChanged(app, event)
            value = app.PeakpreservationEditField.Value;
            
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            % =============================================================
            % 1. VALIDATE PARENT APP
            % =============================================================
            if isempty(app.mainApp)
                
                uialert( ...
                    app.UIFigure, ...
                    ['The parent application is not available.' newline ...
                    'Baseline results cannot be returned.'], ...
                    'Parent app unavailable');
                
                return
            end
            
            try
                parentIsValid = isvalid(app.mainApp);
            catch
                parentIsValid = false;
            end
            
            if ~parentIsValid
                
                uialert( ...
                    app.UIFigure, ...
                    ['The parent application has already been closed.' newline ...
                    'Baseline results cannot be returned.'], ...
                    'Parent app unavailable');
                
                return
            end
            
            
            % =============================================================
            % 2. VALIDATE INPUT MODE AND WAVENUMBER
            % =============================================================
            if isempty(app.mode) || ...
                    ~ismember(app.mode, [1 2])
                
                uialert( ...
                    app.UIFigure, ...
                    'Baseline data mode is invalid.', ...
                    'Invalid mode');
                
                return
            end
            
            if isempty(app.wn)
                
                uialert( ...
                    app.UIFigure, ...
                    'The wavenumber axis is empty.', ...
                    'No wavenumber');
                
                return
            end
            
            app.wn = double(app.wn(:));
            
            
            % =============================================================
            % 3. LEAVE QUICK-VIEW MODE CORRECTLY
            %
            % Quick View only explores candidate parameters.
            % If the user selected one candidate but has not yet applied it,
            % ask whether it should be used.
            % =============================================================
            if app.quickViewActive
                
                choiceQuick = uiconfirm( ...
                    app.UIFigure, ...
                    { ...
                    'Quick View parameter exploration is still active.'; ...
                    ''; ...
                    'Use the currently selected parameter set?'; ...
                    ''; ...
                    'After applying it, baseline correction will be run on'; ...
                    'the complete representative sample before returning.'}, ...
                    'Quick View still active', ...
                    'Options', { ...
                    'Apply selected parameters', ...
                    'Return to baseline view', ...
                    'Cancel'}, ...
                    'DefaultOption', ...
                    'Apply selected parameters', ...
                    'CancelOption', ...
                    'Cancel');
                
                if strcmp(choiceQuick, 'Cancel')
                    return
                end
                
                if strcmp(choiceQuick, 'Return to baseline view')
                    
                    app.exitQuickViewMode(true);
                    
                    return
                end
                
                try
                    app.applySelectedQuickViewParameters();
                    app.exitQuickViewMode(false);
                    
                catch ME
                    
                    uialert( ...
                        app.UIFigure, ...
                        ME.message, ...
                        'Cannot apply Quick View parameters');
                    
                    return
                end
            end
            
            
            % =============================================================
            % 4. CHECK WHETHER BASELINE CORRECTION HAS BEEN RUN
            % =============================================================
            correctionAvailable = false;
            
            switch app.mode
                
                case 1
                    correctionAvailable = ...
                        ~isempty(app.correctedSpectra) && ...
                        isequal( ...
                        size(app.correctedSpectra), ...
                        size(app.spectra));
                    
                case 2
                    correctionAvailable = ...
                        ~isempty(app.correctedCube) && ...
                        isequal( ...
                        size(app.correctedCube), ...
                        size(app.cube));
            end
            
            
            % =============================================================
            % 5. IF NOT YET CORRECTED, ASK TO RUN NOW
            % =============================================================
            if ~correctionAvailable
                
                choiceRun = uiconfirm( ...
                    app.UIFigure, ...
                    { ...
                    'Baseline correction has not been applied yet.'; ...
                    ''; ...
                    sprintf('Current method: %s', ...
                    char(app.baselineParams.method)); ...
                    ''; ...
                    'Run baseline correction now before finishing?'}, ...
                    'Baseline not applied', ...
                    'Options', { ...
                    'Run baseline correction', ...
                    'Cancel'}, ...
                    'DefaultOption', ...
                    'Run baseline correction', ...
                    'CancelOption', ...
                    'Cancel');
                
                if strcmp(choiceRun, 'Cancel')
                    return
                end
                
                try
                    app.FinishButton.Enable = 'off';
                    app.BaselinecorrectionButton.Enable = 'off';
                    
                    drawnow;
                    
                    switch app.mode
                        
                        case 1
                            app.runBaselineSingle();
                            
                        case 2
                            app.runBaselineScan();
                    end
                    
                catch ME
                    
                    app.FinishButton.Enable = 'on';
                    app.BaselinecorrectionButton.Enable = 'on';
                    
                    uialert( ...
                        app.UIFigure, ...
                        ME.message, ...
                        'Baseline correction error');
                    
                    return
                end
                
                app.FinishButton.Enable = 'on';
                app.BaselinecorrectionButton.Enable = 'on';
            end
            
            
            % =============================================================
            % 6. FINAL OUTPUT VALIDATION
            % =============================================================
            switch app.mode
                
                case 1
                    
                    if isempty(app.correctedSpectra)
                        
                        uialert( ...
                            app.UIFigure, ...
                            'Corrected spectra are empty.', ...
                            'Empty output');
                        
                        return
                    end
                    
                    if size(app.correctedSpectra,1) ~= numel(app.wn)
                        
                        uialert( ...
                            app.UIFigure, ...
                            ['Corrected spectra do not match the ' ...
                            'wavenumber dimension.'], ...
                            'Dimension mismatch');
                        
                        return
                    end
                    
                    if size(app.correctedSpectra,2) ~= ...
                            size(app.spectra,2)
                        
                        uialert( ...
                            app.UIFigure, ...
                            ['The number of corrected spectra does not match ' ...
                            'the number of input spectra.'], ...
                            'Dimension mismatch');
                        
                        return
                    end
                    
                    outputSpectra = ...
                        double(app.correctedSpectra);
                    
                    outputCube = [];
                    
                    
                case 2
                    
                    if isempty(app.correctedCube)
                        
                        uialert( ...
                            app.UIFigure, ...
                            'Corrected Raman cube is empty.', ...
                            'Empty output');
                        
                        return
                    end
                    
                    if ndims(app.correctedCube) ~= 3 || ...
                            size(app.correctedCube,3) ~= numel(app.wn)
                        
                        uialert( ...
                            app.UIFigure, ...
                            ['Corrected cube dimensions do not match ' ...
                            'the wavenumber axis.'], ...
                            'Dimension mismatch');
                        
                        return
                    end
                    
                    outputSpectra = [];
                    
                    outputCube = ...
                        double(app.correctedCube);
            end
            
            
            % =============================================================
            % 7. BUILD COMPACT RESULT INFORMATION
            %
            % Do not put corrected spectra/cube inside resultInfo.
            % They are already passed separately.
            % =============================================================
            resultInfo = struct();
            
            resultInfo.SourceApp = ...
                'sg_baseline';
            
            resultInfo.Stage = ...
                'Baseline correction';
            
            resultInfo.Mode = ...
                app.mode;
            
            resultInfo.Method = ...
                char(app.baselineParams.method);
            
            resultInfo.Parameters = ...
                app.baselineParams;
            
            resultInfo.SpectralDefinition = ...
                app.spectralDef;
            
            resultInfo.CompletedAt = ...
                datestr(now, 'yyyy-mm-dd HH:MM:SS');
            
            resultInfo.WavenumberCount = ...
                numel(app.wn);
            
            resultInfo.WavenumberMin = ...
                min(app.wn);
            
            resultInfo.WavenumberMax = ...
                max(app.wn);
            
            resultInfo.OutputSize = [];
            
            switch app.mode
                
                case 1
                    resultInfo.OutputSize = ...
                        size(outputSpectra);
                    
                case 2
                    resultInfo.OutputSize = ...
                        size(outputCube);
            end
            
            
            % =============================================================
            % 8. METRICS
            %
            % metricTable may be a table for single spectra or a struct
            % containing maps for scan mode.
            % =============================================================
            resultInfo.Metrics = ...
                app.metricTable;
            
            resultInfo.MetricSummary = struct();
            
            try
                if app.mode == 1 && ...
                        istable(app.metricTable) && ...
                        ~isempty(app.metricTable)
                    
                    resultInfo.MetricSummary.SpectrumCount = ...
                        height(app.metricTable);
                    
                    if ismember( ...
                            'RMS', ...
                            app.metricTable.Properties.VariableNames)
                        
                        resultInfo.MetricSummary.MedianRMS = ...
                            median( ...
                            app.metricTable.RMS, ...
                            'omitnan');
                        
                        resultInfo.MetricSummary.MaximumRMS = ...
                            max( ...
                            app.metricTable.RMS, ...
                            [], ...
                            'omitnan');
                    end
                    
                    if ismember( ...
                            'Curvature', ...
                            app.metricTable.Properties.VariableNames)
                        
                        resultInfo.MetricSummary.MedianCurvature = ...
                            median( ...
                            app.metricTable.Curvature, ...
                            'omitnan');
                    end
                    
                    if ismember( ...
                            'NegRatio', ...
                            app.metricTable.Properties.VariableNames)
                        
                        resultInfo.MetricSummary.MedianNegativeRatio = ...
                            median( ...
                            app.metricTable.NegRatio, ...
                            'omitnan');
                        
                        resultInfo.MetricSummary.FractionAbove15PercentNegative = ...
                            mean( ...
                            app.metricTable.NegRatio > 0.15, ...
                            'omitnan');
                    end
                    
                    if ismember( ...
                            'PeakPres', ...
                            app.metricTable.Properties.VariableNames)
                        
                        resultInfo.MetricSummary.MedianPeakPreservation = ...
                            median( ...
                            app.metricTable.PeakPres, ...
                            'omitnan');
                    end
                    
                elseif app.mode == 2 && ...
                        isstruct(app.metricTable)
                    
                    if isfield(app.metricTable, 'RMSMap')
                        
                        resultInfo.MetricSummary.MedianRMS = ...
                            median( ...
                            app.metricTable.RMSMap(:), ...
                            'omitnan');
                        
                        resultInfo.MetricSummary.MaximumRMS = ...
                            max( ...
                            app.metricTable.RMSMap(:), ...
                            [], ...
                            'omitnan');
                    end
                    
                    if isfield( ...
                            app.metricTable, ...
                            'NegativeRatioMap')
                        
                        negValues = ...
                            app.metricTable.NegativeRatioMap(:);
                        
                        resultInfo.MetricSummary.MedianNegativeRatio = ...
                            median( ...
                            negValues, ...
                            'omitnan');
                        
                        resultInfo.MetricSummary.FractionAbove15PercentNegative = ...
                            mean( ...
                            negValues > 0.15, ...
                            'omitnan');
                    end
                end
                
            catch
                % Metrics remain available in resultInfo.Metrics even when
                % compact summary calculation is not possible.
            end
            
            
            % =============================================================
            % 9. CONFIRM RETURN TO PARENT
            % =============================================================
            choiceFinish = uiconfirm( ...
                app.UIFigure, ...
                { ...
                'Return this baseline result to the parent app?'; ...
                ''; ...
                sprintf('Method: %s', resultInfo.Method); ...
                sprintf('Output size: %s', ...
                mat2str(resultInfo.OutputSize)); ...
                ''; ...
                ['For scan_single_extract, this result represents only ' ...
                'the representative scan sample used to confirm parameters.']; ...
                ['The complete scan cube will be processed later using ' ...
                'these confirmed parameters.']}, ...
                'Finish baseline correction', ...
                'Options', { ...
                'Return result', ...
                'Stay here'}, ...
                'DefaultOption', ...
                'Return result', ...
                'CancelOption', ...
                'Stay here');
            
            if ~strcmp(choiceFinish, 'Return result')
                return
            end
            
            
            % =============================================================
            % 10. RETURN TO PARENT APP
            % =============================================================
            try
                if ~ismethod(app.mainApp, 'receiveBaselineData')
                    
                    error([ ...
                        'The parent app does not implement ' ...
                        'receiveBaselineData().']);
                end
                
                app.mainApp.receiveBaselineData( ...
                    app.mode, ...
                    app.wn, ...
                    outputSpectra, ...
                    outputCube, ...
                    resultInfo);
                if ismethod(app.mainApp, 'saveCheckpointFromChild')
                    app.mainApp.saveCheckpointFromChild("Baseline");
                end
                
            catch ME
                
                uialert( ...
                    app.UIFigure, ...
                    sprintf([ ...
                    'Baseline correction was completed, but the result ' ...
                    'could not be returned to the parent app.\n\n%s'], ...
                    ME.message), ...
                    'Return baseline result error');
                
                return
            end
            
            
            % =============================================================
            % 11. CLOSE BASELINE APP ONLY AFTER SUCCESSFUL RETURN
            % =============================================================
            delete(app.UIFigure);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            
        end

        % Button down function: UIAxes3_5
        function UIAxes3_5ButtonDown(app, event)
            
        end

        % Button down function: UIAxes3_2
        function UIAxes3_2ButtonDown(app, event)
            
        end

        % Button down function: UIAxes3
        function UIAxes3ButtonDown(app, event)
            
        end

        % Button down function: UIAxes3_4
        function UIAxes3_4ButtonDown(app, event)
            
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            
            if isempty(selectedNodes)
                return
            end
            
            nodeData = selectedNodes(1).NodeData;
            
            %% Quick View Tree
            if app.quickViewActive
                if isstruct(nodeData) && ...
                        isfield(nodeData, 'type') && ...
                        strcmp(nodeData.type, 'quick_candidate')
                    
                    rankIndex = nodeData.rankIndex;
                    
                    if rankIndex >= 1 && ...
                            rankIndex <= numel(app.quickViewCandidates)
                        
                        app.showQuickViewCandidate(rankIndex);
                    end
                end
                
                return
            end
            
            %% Normal spectrum Tree
            idx = nodeData;
            
            if app.mode == 1 && ...
                    isnumeric(idx) && ...
                    isscalar(idx) && ...
                    ~isempty(idx)
                
                app.selectedIndex = idx;
                
                if ~isempty(app.correctedSpectra)
                    app.plotCorrectedSingle(idx);
                else
                    app.plotSingleSpectrum(idx);
                end
                
                if ~isempty(app.metricTable) && ...
                        istable(app.metricTable)
                    
                    app.showMetricForSingleSpectrum(idx);
                end
            end
        end

        % Button pushed function: SaveimagesButton
        function SaveimagesButtonPushed(app, event)
            choice = questdlg( ...
                'Which image do you want to save?', ...
                'Save Baseline images', ...
                'Axis 1', ...
                'Axis 2', ...
                'Both', ...
                'Both');
            
            if isempty(choice)
                return
            end
            
            answer = inputdlg( ...
                {'Enter image resolution DPI:'}, ...
                'Save Baseline image', ...
                [1 45], ...
                {'300'});
            
            if isempty(answer)
                return
            end
            
            dpi = str2double(answer{1});
            
            if isnan(dpi) || dpi <= 0
                uialert(app.UIFigure, ...
                    'DPI must be a positive number.', ...
                    'Invalid DPI');
                return
            end
            
            if ~isempty(app.mainApp) && isvalid(app.mainApp)
                basePath = app.mainApp.askImageFilePath("Baseline", "baseline");
                if basePath == ""
                    return
                end
                [outFolder, baseName, ~] = fileparts(basePath);
            else
                outFolder = uigetdir('', 'Select folder to save baseline images');
                if isequal(outFolder, 0)
                    return
                end
                
                answerName = inputdlg( ...
                    {'Image file name, without extension:'}, ...
                    'Save Baseline image', ...
                    [1 55], ...
                    {'baseline'});
                
                if isempty(answerName)
                    return
                end
                
                baseName = strtrim(string(answerName{1}));
                if baseName == ""
                    baseName = "baseline";
                end
                baseName = regexprep(baseName, '[\\/:*?"<>|]', '_');
            end
            
            try
                savedFiles = strings(0,1);
                
                switch choice
                    case 'Axis 1'
                        filePath = fullfile(outFolder, char(baseName + "_axis1.png"));
                        exportgraphics(app.UIAxes, filePath, 'Resolution', dpi);
                        savedFiles(end+1,1) = string(filePath);
                        
                    case 'Axis 2'
                        filePath = fullfile(outFolder, char(baseName + "_axis2.png"));
                        exportgraphics(app.UIAxes2, filePath, 'Resolution', dpi);
                        savedFiles(end+1,1) = string(filePath);
                        
                    case 'Both'
                        filePath1 = fullfile(outFolder, char(baseName + "_axis1.png"));
                        filePath2 = fullfile(outFolder, char(baseName + "_axis2.png"));
                        
                        exportgraphics(app.UIAxes, filePath1, 'Resolution', dpi);
                        exportgraphics(app.UIAxes2, filePath2, 'Resolution', dpi);
                        
                        savedFiles(end+1,1) = string(filePath1);
                        savedFiles(end+1,1) = string(filePath2);
                end
                
                msgbox(sprintf('Baseline image saved:\n%s', strjoin(savedFiles, newline)), ...
                    'Save images');
                
            catch ME
                uialert(app.UIFigure, ...
                    sprintf('Cannot save image:\n%s', ME.message), ...
                    'Save image error');
            end
        end

        % Button pushed function: WorstspectrumButton
        function WorstspectrumButtonPushed(app, event)
            if app.mode ~= 1
                uialert(app.UIFigure, ...
                    'Worst spectrum view is currently for single spectra only.', ...
                    'Unavailable');
                return
            end
            if isempty(app.metricTable)
                uialert(app.UIFigure, ...
                    'Please run baseline correction first.', ...
                    'No metrics');
                return
            end
            T = app.metricTable;
            zRMS = app.safeZscore(T.RMS);
            zCurv = app.safeZscore(T.Curvature);
            zNeg = app.safeZscore(T.NegRatio);
            if all(isnan(T.PeakPres))
                zPeakLoss = zeros(size(zRMS));
            else
                peakLoss = abs(1 - T.PeakPres);
                zPeakLoss = app.safeZscore(peakLoss);
            end
            score = zRMS + zCurv + zNeg + zPeakLoss;
            nWorst = min(5, numel(score));
            [~, order] = sort(score, 'descend');
            worstIdx = order(1:nWorst);
            meanCorr = mean(app.correctedSpectra, 2, 'omitnan');
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, app.wn, meanCorr, 'k', 'LineWidth', 2);
            for k = 1:numel(worstIdx)
                plot(app.UIAxes, app.wn, app.correctedSpectra(:,worstIdx(k)), ...
                    'r', 'LineWidth', 1);
            end
            hold(app.UIAxes, 'off');
            xlabel(app.UIAxes, 'Wavenumber');
            ylabel(app.UIAxes, 'Corrected intensity');
            title(app.UIAxes, 'Mean corrected spectrum + worst spectra');
            legend(app.UIAxes, {'Mean corrected', 'Worst spectra'}, 'Location', 'best');
            xlim(app.UIAxes, [min(app.wn), max(app.wn)]);
            cla(app.UIAxes2);
            bar(app.UIAxes2, score);
            hold(app.UIAxes2, 'on');
            bar(app.UIAxes2, worstIdx, score(worstIdx));
            hold(app.UIAxes2, 'off');
            xlabel(app.UIAxes2, 'Spectrum index');
            ylabel(app.UIAxes2, 'Badness score');
            title(app.UIAxes2, 'Worst spectrum score');
            app.markWorstSpectraInTree(worstIdx);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
        end

        % Button pushed function: WnfilterButton
        function WnfilterButtonPushed(app, event)
            txt = strtrim(string(app.EditField.Value));
            
            if txt == ""
                uialert(app.UIFigure, ...
                    'Enter WN range first, for example: 500-1800 or 500 1800.', ...
                    'Empty WN filter');
                return
            end
            
            nums = regexp(char(txt), '[-+]?\d*\.?\d+', 'match');
            nums = str2double(nums);
            
            if numel(nums) < 2 || any(isnan(nums(1:2)))
                uialert(app.UIFigure, ...
                    'Invalid WN range. Use format: 500-1800 or 500 1800.', ...
                    'Invalid WN filter');
                return
            end
            
            lo = min(nums(1), nums(2));
            hi = max(nums(1), nums(2));
            
            if hi < min(app.wn) || lo > max(app.wn)
                uialert(app.UIFigure, ...
                    'Selected range is outside the current wavenumber axis.', ...
                    'Invalid WN filter');
                return
            end
            
            lo = max(lo, min(app.wn));
            hi = min(hi, max(app.wn));
            
            choice = questdlg( ...
                'Apply WN filter to which axis?', ...
                'WN filter', ...
                'Axis 1', ...
                'Axis 2', ...
                'Both', ...
                'Both');
            
            if isempty(choice)
                return
            end
            
            switch choice
                case 'Axis 1'
                    xlim(app.UIAxes, [lo hi]);
                    
                case 'Axis 2'
                    xlim(app.UIAxes2, [lo hi]);
                    
                case 'Both'
                    xlim(app.UIAxes, [lo hi]);
                    xlim(app.UIAxes2, [lo hi]);
            end
        end

        % Button pushed function: QuickviewButton
        function QuickviewButtonPushed(app, event)
            if isempty(app.spectralDef)
                uialert( ...
                    app.UIFigure, ...
                    ['Please define the spectral ROI and evaluation ' ...
                    'regions before using Quick View.'], ...
                    'Spectral definition required');
                return
            end
            
            if isempty(app.baselineParams) || ...
                    ~isfield(app.baselineParams, 'method') || ...
                    isempty(app.baselineParams.method)
                
                uialert( ...
                    app.UIFigure, ...
                    'Please select a baseline method first.', ...
                    'No baseline method');
                return
            end
            
            if app.quickViewActive
                app.exitQuickViewMode(true);
            end
            
            method = app.baselineParams.method;
            
            if strcmp(method, 'Peak stripping')
                uialert( ...
                    app.UIFigure, ...
                    sprintf([ ...
                    'Peak stripping parameter exploration is not ' ...
                    'available yet.\n\n' ...
                    'The current external function does not receive ' ...
                    'its parameter set.']), ...
                    'Peak stripping limitation');
                return
            end
            
            progressDialog = uiprogressdlg( ...
                app.UIFigure, ...
                'Title', 'Baseline Quick View', ...
                'Message', ...
                'Preparing representative spectrum...', ...
                'Indeterminate', 'off', ...
                'Value', 0);
            
            try
                %% 1. Representative spectrum
                previewSpectrum = ...
                    app.getQuickViewSpectrum();
                
                progressDialog.Value = 0.08;
                progressDialog.Message = ...
                    'Generating parameter grid...';
                
                %% 2. Parameter candidates
                parameterSets = ...
                    app.buildQuickViewParameterGrid();
                
                nCandidates = numel(parameterSets);
                nWn = numel(app.wn);
                
                emptyCandidate = struct( ...
                    'params', struct(), ...
                    'baseline', nan(nWn,1), ...
                    'corrected', nan(nWn,1), ...
                    'RMS', NaN, ...
                    'Curvature', NaN, ...
                    'NegRatio', NaN, ...
                    'PeakPres', NaN, ...
                    'Score', NaN, ...
                    'Rank', NaN, ...
                    'Status', "", ...
                    'Acceptable', false, ...
                    'Label', "");
                
                candidates = repmat( ...
                    emptyCandidate, nCandidates, 1);
                
                %% 3. Evaluate all candidates
                for k = 1:nCandidates
                    progressDialog.Value = ...
                        0.15 + 0.65 * k / nCandidates;
                    
                    progressDialog.Message = sprintf( ...
                        'Testing candidate %d of %d...', ...
                        k, nCandidates);
                    
                    candidates(k).params = ...
                        parameterSets(k).params;
                    
                    [base, corrected] = ...
                        app.runQuickViewOneSet( ...
                        previewSpectrum, ...
                        method, ...
                        parameterSets(k).params);
                    
                    candidates(k).baseline = base;
                    candidates(k).corrected = corrected;
                    
                    [ ...
                        candidates(k).RMS, ...
                        candidates(k).Curvature, ...
                        candidates(k).NegRatio, ...
                        candidates(k).PeakPres] = ...
                        app.computeMetricsOneSpectrum( ...
                        previewSpectrum, ...
                        base, ...
                        corrected);
                    
                    drawnow limitrate
                end
                
                progressDialog.Value = 0.84;
                progressDialog.Message = ...
                    'Ranking parameter candidates...';
                
                %% 4. Store Quick View state
                app.quickViewActive = true;
                app.quickViewMethod = string(method);
                app.quickViewRaw = previewSpectrum;
                
                candidates = ...
                    app.rankQuickViewCandidates(candidates);
                
                app.quickViewCandidates = candidates;
                app.quickViewSelectedRank = 1;
                
                progressDialog.Value = 0.93;
                progressDialog.Message = ...
                    'Updating Tree and plots...';
                
                %% 5. Display ranked candidates
                app.populateQuickViewTree();
                app.showQuickViewCandidate(1);
                
                progressDialog.Value = 1;
                progressDialog.Message = ...
                    'Quick View completed.';
                
                pause(0.1);
                close(progressDialog);
                
            catch ME
                if exist('progressDialog', 'var') && ...
                        isvalid(progressDialog)
                    
                    close(progressDialog);
                end
                
                if app.quickViewActive
                    app.exitQuickViewMode(true);
                end
                
                uialert( ...
                    app.UIFigure, ...
                    sprintf( ...
                    'Quick View failed:\n\n%s', ...
                    ME.message), ...
                    'Quick View error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.GridLayout);
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.BaselineactionPanel = uipanel(app.GridLayout);
            app.BaselineactionPanel.Title = 'Baseline action';
            app.GridLayout2 = uigridlayout(app.BaselineactionPanel);
            app.MethodsDropDownLabel = uilabel(app.GridLayout2);
            app.MethodsDropDownLabel.Text = 'Methods';
            app.MethodsDropDown = uidropdown(app.GridLayout2);
            app.MethodsDropDown.Items = {'', 'Peak stripping', 'ALS', 'ArPLS', 'AirPLS'};
            app.MethodsDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodsDropDownValueChanged, true);
            app.MethodsDropDown.Value = '';
            app.BaselinesettingsButton = uibutton(app.GridLayout2, 'push');
            app.BaselinesettingsButton.ButtonPushedFcn = createCallbackFcn(app, @BaselinesettingsButtonPushed, true);
            app.BaselinesettingsButton.Text = 'Baseline settings';
            app.BaselinecorrectionButton = uibutton(app.GridLayout2, 'push');
            app.BaselinecorrectionButton.ButtonPushedFcn = createCallbackFcn(app, @BaselinecorrectionButtonPushed, true);
            app.BaselinecorrectionButton.Text = 'Baseline correction';
            app.QuickviewButton = uibutton(app.GridLayout2, 'push');
            app.QuickviewButton.ButtonPushedFcn = createCallbackFcn(app, @QuickviewButtonPushed, true);
            app.QuickviewButton.Text = 'Quick view';
            app.Tree = uitree(app.GridLayout);
            app.Tree.Multiselect = 'on';
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Node = uitreenode(app.Tree);
            app.Node.Text = 'Node';
            app.Node2 = uitreenode(app.Node);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.Node);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.Node);
            app.Node4.Text = 'Node4';
            app.SpectralevaluationPanel = uipanel(app.GridLayout);
            app.SpectralevaluationPanel.Title = 'Spectral evaluation';
            app.GridLayout6 = uigridlayout(app.SpectralevaluationPanel);
            app.ResidualRMSEditFieldLabel = uilabel(app.GridLayout6);
            app.ResidualRMSEditFieldLabel.Text = 'Residual RMS';
            app.ResidualRMSEditField = uieditfield(app.GridLayout6, 'text');
            app.ResidualRMSEditField.ValueChangedFcn = createCallbackFcn(app, @ResidualRMSEditFieldValueChanged, true);
            app.CurvatureEditFieldLabel = uilabel(app.GridLayout6);
            app.CurvatureEditFieldLabel.Text = 'Curvature';
            app.CurvatureEditField = uieditfield(app.GridLayout6, 'text');
            app.CurvatureEditField.ValueChangedFcn = createCallbackFcn(app, @CurvatureEditFieldValueChanged, true);
            app.NegativeresidualratioLabel = uilabel(app.GridLayout6);
            app.NegativeresidualratioLabel.Text = {'Negative '; 'residual ratio'};
            app.NegativeresidualratioEditField = uieditfield(app.GridLayout6, 'text');
            app.NegativeresidualratioEditField.ValueChangedFcn = createCallbackFcn(app, @NegativeresidualratioEditFieldValueChanged, true);
            app.PeakpreservationLabel = uilabel(app.GridLayout6);
            app.PeakpreservationLabel.Text = {'Peak '; 'preservation'};
            app.PeakpreservationEditField = uieditfield(app.GridLayout6, 'text');
            app.PeakpreservationEditField.ValueChangedFcn = createCallbackFcn(app, @PeakpreservationEditFieldValueChanged, true);
            app.WorstspectrumButton = uibutton(app.GridLayout6, 'push');
            app.WorstspectrumButton.ButtonPushedFcn = createCallbackFcn(app, @WorstspectrumButtonPushed, true);
            app.WorstspectrumButton.Text = 'Worst spectrum';
            app.Panel = uipanel(app.GridLayout);
            app.RamanAppBaselinecorrectionLabel = uilabel(app.Panel);
            app.RamanAppBaselinecorrectionLabel.Text = 'Raman App. Baseline correction';
            app.Panel_2 = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.Panel_2);
            app.UIAxes3_2 = uiaxes(app.GridLayout3);
            title(app.UIAxes3_2, 'Curvature')
            zlabel(app.UIAxes3_2, 'Z')
            app.UIAxes3_2.XTickLabel = '';
            app.UIAxes3_2.YTickLabel = '';
            app.UIAxes3_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes3_2ButtonDown, true);
            app.UIAxes3_5 = uiaxes(app.GridLayout3);
            title(app.UIAxes3_5, 'Residual RMS')
            zlabel(app.UIAxes3_5, 'Z')
            app.UIAxes3_5.XTickLabel = '';
            app.UIAxes3_5.YTickLabel = '';
            app.UIAxes3_5.ButtonDownFcn = createCallbackFcn(app, @UIAxes3_5ButtonDown, true);
            app.Panel_3 = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.Panel_3);
            app.UIAxes3 = uiaxes(app.GridLayout4);
            title(app.UIAxes3, 'Negative residual ratio')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.XTickLabel = '';
            app.UIAxes3.YTickLabel = '';
            app.UIAxes3.ButtonDownFcn = createCallbackFcn(app, @UIAxes3ButtonDown, true);
            app.Panel_4 = uipanel(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.Panel_4);
            app.UIAxes3_4 = uiaxes(app.GridLayout5);
            title(app.UIAxes3_4, 'Peak preservation')
            zlabel(app.UIAxes3_4, 'Z')
            app.UIAxes3_4.XTickLabel = '';
            app.UIAxes3_4.YTickLabel = '';
            app.UIAxes3_4.ButtonDownFcn = createCallbackFcn(app, @UIAxes3_4ButtonDown, true);
            app.FinishButton = uibutton(app.GridLayout, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.GridLayout7 = uigridlayout(app.GridLayout);
            app.EditField = uieditfield(app.GridLayout7, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.WnfilterButton = uibutton(app.GridLayout7, 'push');
            app.WnfilterButton.ButtonPushedFcn = createCallbackFcn(app, @WnfilterButtonPushed, true);
            app.WnfilterButton.Text = 'Wn filter';
            app.SaveimagesButton = uibutton(app.GridLayout, 'push');
            app.SaveimagesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveimagesButtonPushed, true);
            app.SaveimagesButton.Text = 'Save images';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sg_baseline

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end