classdef sg_cosmic < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        GridLayout6                 matlab.ui.container.GridLayout
        SaveimagesButton            matlab.ui.control.Button
        FinishButton                matlab.ui.control.Button
        CorrectionPanel             matlab.ui.container.Panel
        GridLayout5                 matlab.ui.container.GridLayout
        SettingsButton_2            matlab.ui.control.Button
        ApplyforallButton           matlab.ui.control.Button
        TrialButton                 matlab.ui.control.Button
        MethodsDropDown_2           matlab.ui.control.DropDown
        MethodsDropDown_2Label      matlab.ui.control.Label
        Tree                        matlab.ui.container.Tree
        Node                        matlab.ui.container.TreeNode
        Node2                       matlab.ui.container.TreeNode
        Node3                       matlab.ui.container.TreeNode
        Node4                       matlab.ui.container.TreeNode
        DetectionPanel              matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        FilterButton                matlab.ui.control.Button
        ScopeDropDown               matlab.ui.control.DropDown
        ScopeDropDownLabel          matlab.ui.control.Label
        ResetButton                 matlab.ui.control.Button
        LockcosmiclistButton        matlab.ui.control.Button
        Panel                       matlab.ui.container.Panel
        GridLayout4                 matlab.ui.container.GridLayout
        RejectButton                matlab.ui.control.Button
        UncertainButton             matlab.ui.control.Button
        AgreeButton                 matlab.ui.control.Button
        CosmicspikedetectionButton  matlab.ui.control.Button
        SettingsButton              matlab.ui.control.Button
        MethodsDropDown             matlab.ui.control.DropDown
        MethodsDropDownLabel        matlab.ui.control.Label
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        parentApp
        mode
        wn
        spectra
        cube
        originalCubeSize
        spectraMatrix
        correctedSpectraMatrix
        trialSpectraMatrix
        candidates struct = struct([])
        currentIdx double = []
        lockedCandidateIdx double = []
        lockedList logical = false
        detectionMethod string = "Modified Z-score"
        correctionMethod string = "pchip"
        detectionRanges double = [400 1700; 2800 3600]
        zThreshold double = 8
        derivativeThreshold double = 8
        medianWindow double = 7
        maxSpikeWidthPts double = 5
        minProminence double = 6
        localPadWN double = 80
        correctionExpandPts double = 1
        correctionLocalPadPts double = 15
        backgroundMean double = []
        backgroundStd double = []
        hasTrial logical = false
        hasAppliedCorrection logical = false
        correctionGapPts double = 3
        spectralDef
        % =========================================================
        % Multi-pass cosmic workflow
        % =========================================================
        workingSpectraMatrix double = []
        passInputSpectraMatrix double = []
        
        cosmicPassNumber double = 1
        cosmicPassMode string = "Standard"
        
        passHistory struct = struct([])
    end
    
    methods (Access = public)
        
        % Function 1
        function receiveData(app, parentApp, mode, wn, spectra, cube)
            app.parentApp = parentApp;
            app.mode = mode;
            app.wn = wn(:);
            if isprop(parentApp, 'spectralDef') && ~isempty(parentApp.spectralDef)
                app.spectralDef = parentApp.spectralDef;
                if isfield(app.spectralDef, 'roiRanges') && ~isempty(app.spectralDef.roiRanges)
                    app.detectionRanges = app.spectralDef.roiRanges;
                else
                    app.detectionRanges = [min(app.wn), max(app.wn)];
                end
            else
                app.spectralDef = [];
                app.detectionRanges = [min(app.wn), max(app.wn)];
            end
            switch mode
                case 1
                    app.spectra = spectra;
                    app.cube = [];
                    app.spectraMatrix = spectra;
                case 2
                    app.spectra = [];
                    app.cube = cube;
                    app.originalCubeSize = size(cube);
                    ny = size(cube,1);
                    nx = size(cube,2);
                    nWn = size(cube,3);
                    
                    app.spectraMatrix = reshape( ...
                        permute(cube, [3 2 1]), ...
                        nWn, nx * ny);
            end
            app.correctedSpectraMatrix = app.spectraMatrix;
            app.trialSpectraMatrix = [];
            % Multi-pass workflow
            app.workingSpectraMatrix = app.spectraMatrix;
            app.passInputSpectraMatrix = app.spectraMatrix;
            app.cosmicPassNumber = 1;
            app.cosmicPassMode = "Standard";
            app.passHistory = struct([]);
            app.candidates = struct([]);
            app.currentIdx = [];
            app.lockedCandidateIdx = [];
            app.lockedList = false;
            app.hasTrial = false;
            app.hasAppliedCorrection = false;
            app.MethodsDropDown.Items = { ...
                '', ...
                'Modified Z-score', ...
                'Derivative', ...
                'Width / prominence', ...
                'Hybrid expert'};
            app.MethodsDropDown.Value = '';
            app.MethodsDropDown.Tooltip = app.getDetectionMethodDescription();
            app.MethodsDropDown_2.Items = { ...
                '', ...
                'Linear', ...
                'PCHIP', ...
                'Makima', ...
                'Spline'};
            app.MethodsDropDown_2.Value = '';
            app.MethodsDropDown_2.Tooltip = app.getCorrectionMethodDescription();
            app.ScopeDropDown.Items = { ...
                '', ...
                'All candidates', ...
                'Sort by score', ...
                'Pending only', ...
                'Agreed only', ...
                'Uncertain only', ...
                'Rejected only'};
            app.ScopeDropDown.Value = '';
            app.computeBackgroundStats();
            app.clearTree();
            app.plotOverview();
        end
        
    end


    methods (Access = private)
        
        function computeBackgroundStats(app)
            if isempty(app.spectraMatrix)
                app.backgroundMean = [];
                app.backgroundStd = [];
                return
            end
            app.backgroundMean = mean(app.spectraMatrix, 2, 'omitnan');
            app.backgroundStd = std(app.spectraMatrix, 0, 2, 'omitnan');
        end
        
        function clearTree(app)
            delete(app.Tree.Children);
        end
        
        function plotMeanSD(app, ax, fullRange)
            x = app.wn(:);
            mu = app.backgroundMean;
            sd = app.backgroundStd;
            cla(ax);
            fill(ax, ...
                [x; flipud(x)], ...
                [mu + sd; flipud(mu - sd)], ...
                [0.85 0.85 0.85], ...
                'EdgeColor', 'none', ...
                'FaceAlpha', 0.45);
            hold(ax, 'on');
            plot(ax, x, mu, 'k', 'LineWidth', 1.2);
            hold(ax, 'off');
            xlabel(ax, 'Raman shift (cm^{-1})');
            ylabel(ax, 'Intensity');
            ax.Box = 'on';
            if fullRange
                xlim(ax, [min(x), max(x)]);
            end
        end
        
        function plotOverview(app)
            app.plotMeanSD(app.UIAxes, true);
            title(app.UIAxes, 'Axis 1: Mean ± SD overview');
            app.plotMeanSD(app.UIAxes2, true);
            title(app.UIAxes2, 'Axis 2: Full spectral context');
        end
        
        function txt = getDetectionMethodDescription(app)
            switch string(app.MethodsDropDown.Value)
                case "Modified Z-score"
                    txt = ['Modified Z-score detects isolated outliers using robust statistics. ' ...
                        'Recommended default for most Raman spectra.'];
                case "Derivative"
                    txt = ['Derivative detection detects extremely sharp intensity changes. ' ...
                        'Good for narrow cosmic spikes, but may flag sharp Raman peaks.'];
                case "Width / prominence"
                    txt = ['Width/prominence detection uses peak width and height. ' ...
                        'Useful for dense Raman spectra with many real peaks.'];
                otherwise
                    txt = ['Hybrid expert mode combines residual, derivative, and width rules. ' ...
                        'More flexible but requires careful tuning.'];
            end
        end
        
        function txt = getCorrectionMethodDescription(app)
            switch string(app.MethodsDropDown_2.Value)
                case "Linear"
                    txt = 'Linear interpolation is safest and least aggressive.';
                case "PCHIP"
                    txt = 'PCHIP preserves local spectral shape and is recommended.';
                case "Makima"
                    txt = 'Makima gives smooth interpolation with less overshoot than spline.';
                otherwise
                    txt = 'Spline is very smooth but may overshoot near sharp Raman bands.';
            end
        end
        
        function ok = showDetectionSettings(app)
            method = string(app.MethodsDropDown.Value);
            intro = sprintf([ ...
                'Recommended examples:\n\n' ...
                'General Raman / balanced:\n' ...
                '  Median window = 7, Z threshold = 8, max width = 5\n\n' ...
                'Dense Raman peaks:\n' ...
                '  Median window = 5, Z threshold = 10, max width = 3\n\n' ...
                'Noisy biological spectra:\n' ...
                '  Median window = 11, Z threshold = 6, max width = 6\n\n' ...
                'Parameter meaning:\n' ...
                '- Increasing threshold reduces false positives but may miss weak spikes.\n' ...
                '- Increasing window smooths more but may miss narrow artifacts.\n' ...
                '- Increasing max width accepts broader spikes but may include real Raman peaks.\n\n' ...
                'Current method: %s'], method);
            
            answer = inputdlg( ...
                {intro, ...
                'Detection ranges from spectral definition, editable here:', ...
                'Median window:', ...
                'Z threshold:', ...
                'Derivative threshold:', ...
                'Maximum spike width points:', ...
                'Minimum prominence:', ...
                'Local display ± cm^{-1}:'}, ...
                'Cosmic spike detection settings', ...
                [8 70; 1 70; 1 70; 1 70; 1 70; 1 70; 1 70; 1 70], ...
                {'', ...
                app.rangesToText(), ...
                num2str(app.medianWindow), ...
                num2str(app.zThreshold), ...
                num2str(app.derivativeThreshold), ...
                num2str(app.maxSpikeWidthPts), ...
                num2str(app.minProminence), ...
                num2str(app.localPadWN)});
            if isempty(answer)
                ok = false;
                return
            end
            app.detectionRanges = app.parseRanges(answer{2});
            app.medianWindow = max(3, round(str2double(answer{3})));
            if mod(app.medianWindow, 2) == 0
                app.medianWindow = app.medianWindow + 1;
            end
            app.zThreshold = str2double(answer{4});
            app.derivativeThreshold = str2double(answer{5});
            app.maxSpikeWidthPts = max(1, round(str2double(answer{6})));
            app.minProminence = str2double(answer{7});
            app.localPadWN = str2double(answer{8});
            ok = true;
        end
        
        function txt = rangesToText(app)
            parts = strings(size(app.detectionRanges,1),1);
            for k = 1:size(app.detectionRanges,1)
                parts(k) = sprintf('%.0f-%.0f', app.detectionRanges(k,1), app.detectionRanges(k,2));
            end
            txt = strjoin(parts, ';');
        end
        
        function ranges = parseRanges(app,txt)
            txt = strtrim(string(txt));
            if txt == ""
                if ~isempty(app.spectralDef) && isfield(app.spectralDef,'roiRanges') && ~isempty(app.spectralDef.roiRanges)
                    ranges = app.spectralDef.roiRanges;
                else
                    ranges = [min(app.wn),max(app.wn)];
                end
                return
            end
            groups = split(txt,';');
            ranges = [];
            for k = 1:numel(groups)
                nums = regexp(char(groups(k)),'[-+]?\d*\.?\d+','match');
                if numel(nums) >= 2
                    a = str2double(nums{1});
                    b = str2double(nums{2});
                    ranges(end+1,:) = [min(a,b),max(a,b)];
                end
            end
            if isempty(ranges)
                if ~isempty(app.spectralDef) && isfield(app.spectralDef,'roiRanges') && ~isempty(app.spectralDef.roiRanges)
                    ranges = app.spectralDef.roiRanges;
                else
                    ranges = [min(app.wn),max(app.wn)];
                end
            end
        end
        
        function candidates = detectAllCandidates(app)
            candidates = struct([]);
            count = 0;
            nSpectra = size(app.passInputSpectraMatrix, 2);
            d = uiprogressdlg(app.UIFigure, ...
                'Title', 'Detecting cosmic spikes', ...
                'Message', sprintf('Scanning spectrum 0 / %d...', nSpectra), ...
                'Indeterminate', 'off', ...
                'Cancelable', 'off');
            cleanupObj = onCleanup(@() close(d));
            for s = 1:nSpectra
                y = app.passInputSpectraMatrix(:,s);
                [mask, score] = app.detectOneSpectrum(y);
                candS = app.maskToCandidates(mask, score, s);
                if ~isempty(candS)
                    for k = 1:numel(candS)
                        count = count + 1;
                        if count == 1
                            candidates = candS(k);
                        else
                            candidates(count) = candS(k);
                        end
                    end
                end
                d.Value = s / nSpectra;
                d.Message = sprintf('Scanning spectrum %d / %d... Candidates: %d', ...
                    s, nSpectra, count);
                drawnow limitrate
            end
            if count == 0
                candidates = struct([]);
            end
        end
        
        function [maskAll, scoreAll] = detectOneSpectrum(app, y)
            y = y(:);
            maskAll = false(size(y));
            scoreAll = zeros(size(y));
            for r = 1:size(app.detectionRanges,1)
                idxGlobal = find(app.wn >= app.detectionRanges(r,1) & ...
                    app.wn <= app.detectionRanges(r,2));
                if ~isempty(app.spectralDef) && isfield(app.spectralDef, 'excludeMask') ...
                        && numel(app.spectralDef.excludeMask) == numel(app.wn)
                    idxGlobal = idxGlobal(~app.spectralDef.excludeMask(idxGlobal));
                end
                if numel(idxGlobal) < 10
                    continue
                end
                ySeg = y(idxGlobal);
                switch string(app.detectionMethod)
                    case "Modified Z-score"
                        [maskSeg, scoreSeg] = app.detectModifiedZ(ySeg);
                    case "Derivative"
                        [maskSeg, scoreSeg] = app.detectDerivative(ySeg);
                    case "Width / prominence"
                        [maskSeg, scoreSeg] = app.detectWidthProminence(ySeg);
                    otherwise
                        [maskSeg, scoreSeg] = app.detectHybrid(ySeg);
                end
                maskAll(idxGlobal) = maskAll(idxGlobal) | maskSeg;
                scoreAll(idxGlobal) = max(scoreAll(idxGlobal), scoreSeg);
            end
        end
        
        function [mask, score] = detectModifiedZ(app, y)
            y = y(:);
            yMed = medfilt1(y, app.medianWindow, 'truncate');
            residual = y - yMed;
            madVal = median(abs(residual - median(residual)), 'omitnan');
            z = 0.6745 * residual ./ max(madVal, eps);
            score = abs(z);
            mask = z > app.zThreshold;
        end
        
        % function [mask, score] = detectDerivative(app, y)
        %     y = y(:);
        %     d = diff(y);
        %     madVal = median(abs(d - median(d)), 'omitnan');
        %     z = abs(0.6745 * (d - median(d)) ./ max(madVal, eps));
        %     score = zeros(size(y));
        %     score(1:end-1) = max(score(1:end-1), z);
        %     score(2:end) = max(score(2:end), z);
        %     mask = score > app.derivativeThreshold;
        % end
        
        
        function [mask, score] = detectDerivative(app, y)
            y = double(y(:));
            n = numel(y);
            mask = false(n,1);
            score = zeros(n,1);
            % =========================================================
            % First derivative
            % =========================================================
            d = diff(y);
            % =========================================================
            % Robust derivative statistics
            % =========================================================
            medD = median(d, 'omitnan');
            madD = median( ...
                abs(d - medD), ...
                'omitnan');
            scaleD = max(madD, eps);
            % Signed robust derivative score
            zSigned = ...
                0.6745 * ...
                (d - medD) ./ scaleD;
            zAbs = abs(zSigned);
            % =========================================================
            % Map derivative score back to spectral points
            % =========================================================
            score(1:end-1) = max( ...
                score(1:end-1), ...
                zAbs);
            score(2:end) = max( ...
                score(2:end), ...
                zAbs);
            % =========================================================
            % Significant rising and falling edges
            % =========================================================
            riseEdge = zSigned > app.derivativeThreshold;
            fallEdge = zSigned < -app.derivativeThreshold;
            
            riseIdx = find(riseEdge);
            fallIdx = find(fallEdge);
            
            
            if isempty(riseIdx) || isempty(fallIdx)
                return
            end
            
            
            % =========================================================
            % Pair rising edge with following falling edge
            % =========================================================
            usedFall = false(size(fallIdx));
            
            for i = 1:numel(riseIdx)
                
                leftD = riseIdx(i);
                
                
                % Candidate falling edges after rising edge
                j = find( ...
                    fallIdx > leftD & ...
                    ~usedFall, ...
                    1, ...
                    'first');
                
                
                if isempty(j)
                    continue
                end
                
                
                rightD = fallIdx(j);
                
                
                % Spectral-point width
                widthPts = rightD - leftD + 2;
                
                
                % Reject excessively wide structures
                if widthPts > app.maxSpikeWidthPts
                    continue
                end
                
                
                % Convert derivative indices to spectral indices
                startIdx = max(1, leftD);
                
                endIdx = min( ...
                    n, ...
                    rightD + 1);
                
                
                % Fill entire spike region
                mask(startIdx:endIdx) = true;
                
                
                % Propagate representative score across candidate
                candidateScore = max( ...
                    zAbs(leftD:rightD));
                
                score(startIdx:endIdx) = max( ...
                    score(startIdx:endIdx), ...
                    candidateScore);
                
                
                usedFall(j) = true;
                
            end
            
        end
        
        function [mask, score] = detectWidthProminence(app, y)
            y = y(:);
            score = zeros(size(y));
            mask = false(size(y));
            try
                [pks, locs, widths, prom] = findpeaks(y, ...
                    'MinPeakProminence', app.minProminence);
                for k = 1:numel(locs)
                    if widths(k) <= app.maxSpikeWidthPts
                        mask(locs(k)) = true;
                        score(locs(k)) = prom(k);
                    end
                end
            catch
                [mask, score] = app.detectModifiedZ(y);
            end
        end
        
        function [mask, score] = detectHybrid(app, y)
            [maskZ, scoreZ] = app.detectModifiedZ(y);
            [maskD, scoreD] = app.detectDerivative(y);
            score = max(scoreZ, scoreD);
            mask = maskZ & maskD;
        end
        
        function candidates = maskToCandidates(app, mask, score, spectrumIndex)
            candidates = struct([]);
            if ~any(mask)
                return
            end
            dmask = diff([false; mask(:); false]);
            startIdx = find(dmask == 1);
            endIdx = find(dmask == -1) - 1;
            count = 0;
            for k = 1:numel(startIdx)
                widthPts = endIdx(k) - startIdx(k) + 1;
                if widthPts > app.maxSpikeWidthPts
                    continue
                end
                idxRange = startIdx(k):endIdx(k);
                
                maxScore = max( ...
                    score(idxRange), ...
                    [], ...
                    'omitnan');
                
                
                % =========================================================
                % Candidate center localization
                % =========================================================
                if string(app.MethodsDropDown.Value) == "Derivative"
                    
                    yCandidate = app.passInputSpectraMatrix( ...
                        idxRange, ...
                        spectrumIndex);
                    
                    [~, localMaxIdx] = max( ...
                        yCandidate, ...
                        [], ...
                        'omitnan');
                    
                else
                    
                    [~, localMaxIdx] = max( ...
                        score(idxRange), ...
                        [], ...
                        'omitnan');
                    
                end
                
                
                centerIdx = idxRange(localMaxIdx);
                count = count + 1;
                candidates(count).spectrumIndex = spectrumIndex;
                candidates(count).startIdx = startIdx(k);
                candidates(count).endIdx = endIdx(k);
                candidates(count).centerIdx = centerIdx;
                candidates(count).startWN = app.wn(startIdx(k));
                candidates(count).endWN = app.wn(endIdx(k));
                candidates(count).centerWN = app.wn(centerIdx);
                candidates(count).score = maxScore;
                candidates(count).widthPts = widthPts;
                candidates(count).status = "pending";
            end
        end
        
        function refreshTree(app,nextIdx)
            app.clearTree();
            if isempty(app.candidates)
                return
            end
            visibleIdx = app.getVisibleIdx();
            root = uitreenode(app.Tree);
            root.Text = sprintf('Cosmic candidates (%d)',numel(visibleIdx));
            root.NodeData = [];
            for ii = 1:numel(visibleIdx)
                k = visibleIdx(ii);
                node = uitreenode(root);
                node.NodeData = k;
                node.Text = app.formatCandidateText(k);
            end
            expand(root);
            if isempty(visibleIdx)
                app.currentIdx = [];
                return
            end
            if nargin < 2 || isempty(nextIdx) || ~ismember(nextIdx,visibleIdx)
                nextIdx = visibleIdx(1);
            end
            app.currentIdx = nextIdx;
            node = app.findNodeByCandidateIdx(app.currentIdx);
            if ~isempty(node)
                app.Tree.SelectedNodes = node;
            end
            app.showCandidate(app.currentIdx);
        end
        
        function txt = formatCandidateText(app, k)
            c = app.candidates(k);
            base = sprintf('#%d | S%d | %.1f cm^{-1} | score %.1f', ...
                k, c.spectrumIndex, c.centerWN, c.score);
            switch c.status
                case "agreed"
                    txt = "✓ " + base;
                case "uncertain"
                    txt = "~ " + base;
                case "rejected"
                    txt = "✗ " + app.strikeText(base);
                otherwise
                    txt = "? " + base;
            end
        end
        
        function s2 = strikeText(app, s)
            s = char(s);
            s2 = "";
            for k = 1:numel(s)
                s2 = s2 + string(s(k)) + char(822);
            end
        end
        
        function idx = getVisibleIdx(app)
            idx = 1:numel(app.candidates);
            status = string({app.candidates.status});
            scope = string(app.ScopeDropDown.Value);
            switch scope
                case "Sort by score"
                    scores = [app.candidates.score];
                    [~, order] = sort(scores, 'descend');
                    idx = idx(order);
                case "Pending only"
                    idx = idx(status == "pending");
                case "Agreed only"
                    idx = idx(status == "agreed");
                case "Uncertain only"
                    idx = idx(status == "uncertain");
                case "Rejected only"
                    idx = idx(status == "rejected");
            end
        end
        
        function node = findNodeByCandidateIdx(app, idx)
            node = [];
            roots = app.Tree.Children;
            for r = 1:numel(roots)
                children = roots(r).Children;
                for k = 1:numel(children)
                    if isequal(children(k).NodeData, idx)
                        node = children(k);
                        return
                    end
                end
            end
        end
        
        function showCandidate(app, k)
            if isempty(app.candidates) || isempty(k)
                return
            end
            c = app.candidates(k);
            s = c.spectrumIndex;
            x = app.wn(:);
            y = app.passInputSpectraMatrix(:,s);
            spikeIdx = c.startIdx:c.endIdx;
            if app.lockedList || app.hasTrial || app.hasAppliedCorrection
                app.plotCorrectionCandidate(k);
                return
            end
            app.plotMeanSD(app.UIAxes, false);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, x, y, 'Color', [0 0.25 0.65], 'LineWidth', 1.4);
            plot(app.UIAxes, x(spikeIdx), y(spikeIdx), 'r', 'LineWidth', 3);
            xline(app.UIAxes, c.centerWN, 'r--');
            hold(app.UIAxes, 'off');
            xlim(app.UIAxes, [c.centerWN - app.localPadWN, c.centerWN + app.localPadWN]);
            title(app.UIAxes, sprintf('Axis 1: Local detection | S%d | %.1f cm^{-1}', ...
                s, c.centerWN));
            app.plotMeanSD(app.UIAxes2, true);
            hold(app.UIAxes2, 'on');
            plot(app.UIAxes2, x, y, 'Color', [0 0.25 0.65], 'LineWidth', 1.1);
            plot(app.UIAxes2, x(spikeIdx), y(spikeIdx), 'r', 'LineWidth', 2.5);
            xline(app.UIAxes2, c.centerWN, 'r--');
            hold(app.UIAxes2, 'off');
            title(app.UIAxes2, sprintf('Axis 2: Full spectrum context | S%d', s));
        end
        
        function plotCorrectionCandidate(app, k)
            c = app.candidates(k);
            s = c.spectrumIndex;
            x = app.wn(:);
            yOld = app.passInputSpectraMatrix(:,s);
            if ~isempty(app.trialSpectraMatrix)
                yNew = app.trialSpectraMatrix(:,s);
            else
                yNew = app.correctedSpectraMatrix(:,s);
            end
            app.plotMeanSD(app.UIAxes, false);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, x, yOld, 'Color', [0 0.25 0.65], 'LineWidth', 1.4);
            xline(app.UIAxes, c.centerWN, 'r--');
            hold(app.UIAxes, 'off');
            xlim(app.UIAxes, [c.centerWN - app.localPadWN, c.centerWN + app.localPadWN]);
            title(app.UIAxes, 'Axis 1: Original local region');
            app.plotMeanSD(app.UIAxes2, false);
            hold(app.UIAxes2, 'on');
            plot(app.UIAxes2, x, yNew, 'r', 'LineWidth', 1.4);
            xline(app.UIAxes2, c.centerWN, 'r--');
            hold(app.UIAxes2, 'off');
            xlim(app.UIAxes2, [c.centerWN - app.localPadWN, c.centerWN + app.localPadWN]);
            title(app.UIAxes2, 'Axis 2: Corrected local region');
        end
        
        function setCandidateStatus(app,statusValue)
            if isempty(app.currentIdx) || isempty(app.candidates)
                return
            end
            visibleIdx = app.getVisibleIdx();
            currentPos = find(visibleIdx == app.currentIdx, 1, 'first');
            % Update status
            app.candidates(app.currentIdx).status = string(statusValue);
            % ===== Determine next candidate =====
            visibleIdxAfter = app.getVisibleIdx();
            if isempty(visibleIdxAfter)
                app.currentIdx = [];
                app.clearTree();
                app.plotOverview();
                return
            end
            if isempty(currentPos)
                nextIdx = visibleIdxAfter(1);
                
            else
                % stay near current position
                nextPos = min(currentPos + 1, numel(visibleIdxAfter));
                nextIdx = visibleIdxAfter(nextPos);
            end
            % ===== ONLY update current node text =====
            oldNode = app.findNodeByCandidateIdx(app.currentIdx);
            if ~isempty(oldNode)
                oldNode.Text = app.formatCandidateText(app.currentIdx);
            end
            % ===== Move to next node =====
            app.currentIdx = nextIdx;
            nextNode = app.findNodeByCandidateIdx(nextIdx);
            if ~isempty(nextNode)
                app.Tree.SelectedNodes = nextNode;
                % IMPORTANT:
                drawnow;
                % restore keyboard navigation
                figure(app.UIFigure);
            end
            % ===== Show candidate =====
            app.showCandidate(nextIdx);
        end
        
        function yCorr = correctOneRegion(app, y, startIdx, endIdx)
            n = numel(y);
            s1 = max(1, startIdx - app.correctionExpandPts);
            s2 = min(n, endIdx + app.correctionExpandPts);
            gap = app.correctionGapPts;
            leftStart  = max(1, s1 - gap - app.correctionLocalPadPts);
            leftEnd    = max(1, s1 - gap);
            rightStart = min(n, s2 + gap);
            rightEnd   = min(n, s2 + gap + app.correctionLocalPadPts);
            leftIdx = leftStart:leftEnd;
            rightIdx = rightStart:rightEnd;
            anchorIdx = unique([leftIdx(:); rightIdx(:)]);
            spikeIdx = (s1:s2)';
            yCorr = y;
            if numel(anchorIdx) < 2 || isempty(spikeIdx)
                return
            end
            method = lower(string(app.MethodsDropDown_2.Value));
            switch method
                case "linear"
                    interpMethod = 'linear';
                case "pchip"
                    interpMethod = 'pchip';
                case "makima"
                    interpMethod = 'makima';
                case "spline"
                    interpMethod = 'spline';
                otherwise
                    return
            end
            yCorr(spikeIdx) = interp1( ...
                app.wn(anchorIdx), ...
                y(anchorIdx), ...
                app.wn(spikeIdx), ...
                interpMethod, ...
                'extrap');
        end
        
        function trial = makeTrialMatrix(app, onlyCurrent)
            trial = app.passInputSpectraMatrix;
            if onlyCurrent
                idxList = app.currentIdx;
            else
                idxList = app.lockedCandidateIdx;
            end
            for ii = 1:numel(idxList)
                c = app.candidates(idxList(ii));
                s = c.spectrumIndex;
                y = trial(:,s);
                y = app.correctOneRegion(y, c.startIdx, c.endIdx);
                trial(:,s) = y;
            end
        end
        
        function saveCosmicCheck(app)
            if isempty(app.parentApp) || ~isvalid(app.parentApp)
                return
            end
            outFolder = app.parentApp.getProjectSubFolder("Results", "Cosmic");
            if outFolder == ""
                return
            end
            timestamp = char(datetime('now', 'Format', 'yyyy-MM-dd_HH-mm-ss'));
            writematrix([app.wn(:), app.correctedSpectraMatrix], ...
                fullfile(outFolder, ['cosmic_corrected_spectra_matrix_' timestamp '.txt']), ...
                'Delimiter', 'tab');
            app.saveReport(outFolder);
        end
        
        function saveReport(app, outFolder)
            status = string({app.candidates.status});
            reportFile = fullfile(outFolder, 'cosmic_check_report.txt');
            fid = fopen(reportFile, 'w');
            if fid == -1
                return
            end
            fprintf(fid, 'COSMIC CHECK REPORT\n');
            fprintf(fid, '===================\n\n');
            fprintf(fid, 'Date: %s\n', char(datetime('now')));
            fprintf(fid, 'Detection method: %s\n', char(app.MethodsDropDown.Value));
            fprintf(fid, 'Correction method: %s\n\n', char(app.MethodsDropDown_2.Value));
            fprintf(fid, 'Detected candidates: %d\n', numel(app.candidates));
            fprintf(fid, 'Agreed: %d\n', nnz(status == "agreed"));
            fprintf(fid, 'Uncertain: %d\n', nnz(status == "uncertain"));
            fprintf(fid, 'Rejected: %d\n', nnz(status == "rejected"));
            fprintf(fid, 'Pending: %d\n', nnz(status == "pending"));
            fprintf(fid, 'Corrected candidates: %d\n\n', numel(app.lockedCandidateIdx));
            fclose(fid);
        end
        
        function ok = showCorrectionSettings(app)
            method = string(app.MethodsDropDown_2.Value);
            
            if method == ""
                uialert(app.UIFigure, ...
                    'Please select a correction method first.', ...
                    'No correction method');
                ok = false;
                return
            end
            intro = sprintf([ ...
                'Correction settings control how strongly the selected spike region is replaced.\n\n' ...
                'Recommended examples:\n\n' ...
                'Conservative correction:\n' ...
                '  Expand points = 0, Gap points = 2, Anchor pad = 8\n' ...
                '  Use near sharp or dense Raman bands.\n\n' ...
                'Balanced correction:\n' ...
                '  Expand points = 2, Gap points = 3, Anchor pad = 15\n' ...
                '  Recommended default for most spectra.\n\n' ...
                'Strong correction:\n' ...
                '  Expand points = 5, Gap points = 4, Anchor pad = 25\n' ...
                '  Use for broad or saturated cosmic spikes.\n\n' ...
                'Parameter meaning:\n' ...
                '- Expand points increases the corrected region around the detected spike.\n' ...
                '- Gap points skips points next to the spike so anchors are not contaminated.\n' ...
                '- Anchor pad controls how many neighboring clean points are used for interpolation.\n' ...
                '- Larger values correct more strongly but may distort real Raman peaks.\n\n' ...
                'Method note:\n' ...
                '- Linear: safest, least smooth.\n' ...
                '- PCHIP: shape-preserving, recommended.\n' ...
                '- Makima: smoother, less overshoot than spline.\n' ...
                '- Spline: smoothest but may overshoot.\n\n' ...
                'Current correction method: %s'], method);
            answer = inputdlg( ...
                {intro, ...
                'Expand points around spike:', ...
                'Gap points from spike to anchor:', ...
                'Anchor pad points:'}, ...
                'Cosmic spike correction settings', ...
                [13 75; 1 75; 1 75; 1 75], ...
                {'', ...
                num2str(app.correctionExpandPts), ...
                num2str(app.correctionGapPts), ...
                num2str(app.correctionLocalPadPts)});
            if isempty(answer)
                ok = false;
                return
            end
            vals = str2double(answer(2:4));
            if any(isnan(vals))
                uialert(app.UIFigure, ...
                    'Invalid correction settings.', ...
                    'Input error');
                ok = false;
                return
            end
            app.correctionExpandPts = max(0, round(vals(1)));
            app.correctionGapPts = max(0, round(vals(2)));
            app.correctionLocalPadPts = max(2, round(vals(3)));
            ok = true;
        end
        
        
        function prepareNextCosmicPass(app, passMode)
            
            % =========================================================
            % A correction must exist before starting another pass
            % =========================================================
            if ~app.hasAppliedCorrection
                
                uialert(app.UIFigure, ...
                    ['Apply the current cosmic correction before ' ...
                    'starting another detection pass.'], ...
                    'Correction required');
                
                return
            end
            
            
            % =========================================================
            % Promote latest corrected data as next-pass input
            % =========================================================
            app.passInputSpectraMatrix = ...
                app.workingSpectraMatrix;
            
            
            % =========================================================
            % Advance pass
            % =========================================================
            app.cosmicPassNumber = ...
                app.cosmicPassNumber + 1;
            
            app.cosmicPassMode = string(passMode);
            
            
            % =========================================================
            % Reset pass-specific state only
            % =========================================================
            app.candidates = struct([]);
            
            app.currentIdx = [];
            app.lockedCandidateIdx = [];
            
            app.lockedList = false;
            
            app.trialSpectraMatrix = [];
            
            app.hasTrial = false;
            app.hasAppliedCorrection = false;
            
            
            % =========================================================
            % Recompute overview from current working data
            % =========================================================
            app.backgroundMean = mean( ...
                app.passInputSpectraMatrix, ...
                2, ...
                'omitnan');
            
            app.backgroundStd = std( ...
                app.passInputSpectraMatrix, ...
                0, ...
                2, ...
                'omitnan');
            
            
            % =========================================================
            % Reset UI
            % =========================================================
            app.clearTree();
            app.plotOverview();
            
            
            title(app.UIAxes, ...
                sprintf( ...
                'Pass %d — %s detection', ...
                app.cosmicPassNumber, ...
                app.cosmicPassMode));
            
        end


        function returnToParent(app)
            
            parentObj = app.parentApp;
            
            
            % =========================================================
            % Parent no longer exists
            % =========================================================
            if isempty(parentObj) || ~isvalid(parentObj)
                
                app.UIFigure.CloseRequestFcn = '';
                delete(app.UIFigure);
                
                return
                
            end
            
            
            % =========================================================
            % Determine latest valid output
            % =========================================================
            if ~isempty(app.workingSpectraMatrix)
                
                finalMatrix = app.workingSpectraMatrix;
                
            elseif app.hasAppliedCorrection
                
                finalMatrix = app.correctedSpectraMatrix;
                
            else
                
                finalMatrix = app.spectraMatrix;
                
            end
            
            
            % =========================================================
            % Determine whether data changed
            % =========================================================
            wasCorrected = ...
                ~isequaln(finalMatrix, app.spectraMatrix);
            
            
            % =========================================================
            % Convert back to original data representation
            % =========================================================
            switch app.mode
                
                case 1
                    
                    outSpectra = finalMatrix;
                    outCube = [];
                    
                    
                case 2
                    
                    sz = app.originalCubeSize;
                    
                    ny = sz(1);
                    nx = sz(2);
                    nWn = sz(3);
                    
                    temp = reshape( ...
                        finalMatrix, ...
                        nWn, ...
                        nx, ...
                        ny);
                    
                    outCube = permute( ...
                        temp, ...
                        [3 2 1]);
                    
                    outSpectra = [];
                    
            end
            
            
            % =========================================================
            % Return data to parent
            % =========================================================
            parentObj.receiveCosmicData( ...
                app.mode, ...
                app.wn, ...
                outSpectra, ...
                outCube, ...
                wasCorrected);
            
            
            % =========================================================
            % Save Cosmic-specific output when appropriate
            % =========================================================
            if wasCorrected && parentObj.shouldSaveData
                
                app.correctedSpectraMatrix = finalMatrix;
                
                app.saveCosmicCheck();
                
            end
            
            
            % =========================================================
            % Save resumable parent session
            % =========================================================
            parentObj.saveCheckpointFromChild( ...
                "Cosmic");
            
            
            % =========================================================
            % Show parent again
            % =========================================================
            parentObj.showFromChild( ...
                "Cosmic");
            
            
            % =========================================================
            % Close child only
            % =========================================================
            app.UIFigure.CloseRequestFcn = '';
            
            delete(app.UIFigure);
            
        end


        function UIFigureCloseRequest(app, ~)
            
            choice = questdlg( ...
                sprintf([ ...
                'You are closing Cosmic check.\n\n' ...
                'Current work will be returned to the parent app ' ...
                'and the parent session will be saved.']), ...
                'Close Cosmic check', ...
                'Save and return', ...
                'Cancel', ...
                'Save and return');
            
            
            if ~strcmp(choice, 'Save and return')
                return
            end
            
            
            try
                
                app.returnToParent();
                
            catch ME
                
                uialert(app.UIFigure, ...
                    sprintf( ...
                    'Cannot save and return to parent app.\n\n%s', ...
                    ME.message), ...
                    'Close Cosmic error');
                
            end
            
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Existing startup logic
            
            
            app.UIFigure.CloseRequestFcn = ...
                createCallbackFcn( ...
                app, ...
                @UIFigureCloseRequest, ...
                true);
        end

        % Value changed function: MethodsDropDown
        function MethodsDropDownValueChanged(app, event)
            value = app.MethodsDropDown.Value;
            app.detectionMethod = string(app.MethodsDropDown.Value);
            app.MethodsDropDown.Tooltip = app.getDetectionMethodDescription();
            uialert(app.UIFigure, ...
                app.getDetectionMethodDescription(), ...
                char(app.MethodsDropDown.Value));
        end

        % Button pushed function: SettingsButton
        function SettingsButtonPushed(app, event)
            app.showDetectionSettings();
        end

        % Button pushed function: CosmicspikedetectionButton
        function CosmicspikedetectionButtonPushed(app, event)
            if string(app.MethodsDropDown.Value) == ""
                uialert(app.UIFigure, ...
                    'Please select a detection method first.', ...
                    'No detection method');
                return
            end
            if isempty(app.spectraMatrix)
                uialert(app.UIFigure, 'No data available.', 'No data');
                return
            end
            app.detectionMethod = string(app.MethodsDropDown.Value);
            try
                app.candidates = app.detectAllCandidates();
            catch ME
                uialert(app.UIFigure, ...
                    sprintf('Detection failed:\n%s', ME.message), ...
                    'Detection error');
                return
            end
            app.currentIdx = [];
            app.lockedList = false;
            app.lockedCandidateIdx = [];
            app.hasTrial = false;
            app.hasAppliedCorrection = false;
            if isempty(app.candidates)
                app.clearTree();
                root = uitreenode(app.Tree);
                root.Text = 'No cosmic candidates detected';
                root.NodeData = [];
                app.plotOverview();
                uialert(app.UIFigure, ...
                    ['No cosmic spike candidates detected.' newline newline ...
                    'Try lowering Z threshold or using Derivative / Hybrid expert mode.'], ...
                    'Detection result');
                return
            end
            app.refreshTree();
            uialert(app.UIFigure, ...
                sprintf('Detected %d cosmic spike candidates.', numel(app.candidates)), ...
                'Detection complete');
        end

        % Button pushed function: AgreeButton
        function AgreeButtonPushed(app, event)
            app.setCandidateStatus("agreed");
        end

        % Button pushed function: UncertainButton
        function UncertainButtonPushed(app, event)
            app.setCandidateStatus("uncertain");
        end

        % Button pushed function: RejectButton
        function RejectButtonPushed(app, event)
            if isempty(app.candidates)
                return
            end
            choice = questdlg( ...
                'Choose reject mode:', ...
                'Reject candidates', ...
                'Reject current', ...
                'Reject remaining pending', ...
                'Cancel', ...
                'Reject current');
            switch choice
                case 'Reject current'
                    app.setCandidateStatus("rejected");
                case 'Reject remaining pending'
                    status = string({app.candidates.status});
                    idx = find(status == "pending");
                    for k = idx
                        app.candidates(k).status = "rejected";
                    end
                    app.refreshTree();
            end
        end

        % Button pushed function: LockcosmiclistButton
        function LockcosmiclistButtonPushed(app, event)
            if isempty(app.candidates)
                uialert(app.UIFigure, 'No candidate list available.', 'No candidates');
                return
            end
            status = string({app.candidates.status});
            app.lockedCandidateIdx = find(status == "agreed");
            app.lockedList = true;
            if isempty(app.lockedCandidateIdx)
                uialert(app.UIFigure, ...
                    'No agreed cosmic spikes. Nothing will be corrected.', ...
                    'Locked list');
                return
            end
            app.candidates = app.candidates(app.lockedCandidateIdx);
            app.lockedCandidateIdx = 1:numel(app.candidates);
            app.currentIdx = 1;
            app.refreshTree();
            uialert(app.UIFigure, ...
                sprintf('Cosmic list locked. %d agreed candidates kept.', ...
                numel(app.lockedCandidateIdx)), ...
                'Locked list');
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.candidates = struct([]);
            app.currentIdx = [];
            app.lockedCandidateIdx = [];
            app.lockedList = false;
            app.trialSpectraMatrix = [];
            app.correctedSpectraMatrix = app.spectraMatrix;
            app.hasTrial = false;
            app.hasAppliedCorrection = false;
            app.clearTree();
            app.computeBackgroundStats();
            app.plotOverview();
        end

        % Value changed function: ScopeDropDown
        function ScopeDropDownValueChanged(app, event)
            value = app.ScopeDropDown.Value;
            if ~isempty(app.candidates)
                app.refreshTree();
            end
            
        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)
            if ~isempty(app.candidates)
                app.refreshTree();
            end
        end

        % Value changed function: MethodsDropDown_2
        function MethodsDropDown_2ValueChanged(app, event)
            value = app.MethodsDropDown_2.Value;
            app.correctionMethod = string(app.MethodsDropDown_2.Value);
            app.MethodsDropDown_2.Tooltip = app.getCorrectionMethodDescription();
            uialert(app.UIFigure, ...
                app.getCorrectionMethodDescription(), ...
                char(app.MethodsDropDown_2.Value));
        end

        % Button pushed function: TrialButton
        function TrialButtonPushed(app, event)
            if string(app.MethodsDropDown_2.Value) == ""
                uialert(app.UIFigure, ...
                    'Please select a correction method first.', ...
                    'No correction method');
                return
            end
            if isempty(app.currentIdx)
                uialert(app.UIFigure, ...
                    'Please select one agreed candidate first.', ...
                    'No candidate selected');
                return
            end
            if ~app.lockedList
                uialert(app.UIFigure, ...
                    'Please lock the cosmic list before correction.', ...
                    'List not locked');
                return
            end
            app.trialSpectraMatrix = app.makeTrialMatrix(true);
            app.hasTrial = true;
            app.plotCorrectionCandidate(app.currentIdx);
        end

        % Button pushed function: ApplyforallButton
        function ApplyforallButtonPushed(app, event)
            if string(app.MethodsDropDown_2.Value) == ""
                uialert(app.UIFigure, ...
                    'Please select a correction method first.', ...
                    'No correction method');
                return
            end
            if ~app.lockedList || isempty(app.lockedCandidateIdx)
                uialert(app.UIFigure, ...
                    'Please lock the cosmic list before applying correction.', ...
                    'List not locked');
                return
            end
            app.correctedSpectraMatrix = ...
                app.makeTrialMatrix(false);
            app.trialSpectraMatrix = ...
                app.correctedSpectraMatrix;
            app.workingSpectraMatrix = ...
                app.correctedSpectraMatrix;
            app.hasAppliedCorrection = true;
            app.hasTrial = true;
            if ~isempty(app.currentIdx)
                app.plotCorrectionCandidate(app.currentIdx);
            end
            uialert(app.UIFigure, ...
                sprintf('Correction applied to %d agreed cosmic candidates.', ...
                numel(app.lockedCandidateIdx)), ...
                'Correction applied');
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            if isempty(selectedNodes)
                return
            end
            nodeData = selectedNodes.NodeData;
            if isempty(nodeData)
                return
            end
            app.currentIdx = nodeData;
            app.showCandidate(app.currentIdx);
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            if isempty(app.candidates)
                
                nDetected = 0;
                nAgreed = 0;
                nUncertain = 0;
                nRejected = 0;
                nPending = 0;
                
            else
                
                status = string({app.candidates.status});
                
                nDetected = numel(app.candidates);
                nAgreed = nnz(status == "agreed");
                nUncertain = nnz(status == "uncertain");
                nRejected = nnz(status == "rejected");
                nPending = nnz(status == "pending");
                
            end
            
            
            msg = sprintf([ ...
                'Cosmic check summary:\n\n' ...
                'Pass: %d\n' ...
                'Detected candidates: %d\n' ...
                'Agreed: %d\n' ...
                'Uncertain: %d\n' ...
                'Rejected: %d\n' ...
                'Pending: %d\n\n' ...
                'Save current work and return to parent app?'], ...
                app.cosmicPassNumber, ...
                nDetected, ...
                nAgreed, ...
                nUncertain, ...
                nRejected, ...
                nPending);
            
            
            choice = questdlg( ...
                msg, ...
                'Finish Cosmic check', ...
                'Save and return', ...
                'Cancel', ...
                'Save and return');
            
            
            if ~strcmp(choice, 'Save and return')
                return
            end
            
            
            app.returnToParent();
        end

        % Button pushed function: SettingsButton_2
        function SettingsButton_2Pushed(app, event)
            app.showCorrectionSettings();
        end

        % Button pushed function: SaveimagesButton
        function SaveimagesButtonPushed(app, event)
            choice = questdlg( ...
                'Select which axis to save:', ...
                'Save Cosmic Check images', ...
                'Axis 1', ...
                'Axis 2', ...
                'Both', ...
                'Both');
            
            if isempty(choice)
                return
            end
            
            answer = inputdlg( ...
                {'Enter image resolution DPI:'}, ...
                'Save Cosmic Check images', ...
                [1 45], ...
                {'300'});
            
            if isempty(answer)
                return
            end
            
            dpi = str2double(answer{1});
            
            if isnan(dpi) || dpi <= 0
                uialert(app.UIFigure, ...
                    'DPI must be a positive number.', ...
                    'Invalid DPI');
                return
            end
            
            if ~isempty(app.parentApp) && isvalid(app.parentApp)
                basePath = app.parentApp.askImageFilePath("Cosmic", "cosmic_check");
                
                if basePath == ""
                    return
                end
                
                [outFolder, baseName, ~] = fileparts(basePath);
            else
                outFolder = uigetdir('', 'Select folder to save cosmic images');
                
                if isequal(outFolder, 0)
                    return
                end
                
                answerName = inputdlg( ...
                    {'Image file name, without extension:'}, ...
                    'Save Cosmic Check images', ...
                    [1 55], ...
                    {'cosmic_check'});
                
                if isempty(answerName)
                    return
                end
                
                baseName = strtrim(string(answerName{1}));
                
                if baseName == ""
                    baseName = "cosmic_check";
                end
                
                baseName = regexprep(baseName, '[\\/:*?"<>|]', '_');
                timestamp = char(datetime('now', 'Format', 'yyyy-MM-dd_HH-mm-ss'));
                baseName = baseName + "_" + timestamp;
            end
            
            try
                savedFiles = strings(0,1);
                
                switch choice
                    case 'Axis 1'
                        file1 = fullfile(outFolder, char(baseName + "_axis1.png"));
                        exportgraphics(app.UIAxes, file1, 'Resolution', dpi);
                        savedFiles(end+1,1) = string(file1);
                        
                    case 'Axis 2'
                        file2 = fullfile(outFolder, char(baseName + "_axis2.png"));
                        exportgraphics(app.UIAxes2, file2, 'Resolution', dpi);
                        savedFiles(end+1,1) = string(file2);
                        
                    case 'Both'
                        file1 = fullfile(outFolder, char(baseName + "_axis1.png"));
                        file2 = fullfile(outFolder, char(baseName + "_axis2.png"));
                        
                        exportgraphics(app.UIAxes, file1, 'Resolution', dpi);
                        exportgraphics(app.UIAxes2, file2, 'Resolution', dpi);
                        
                        savedFiles(end+1,1) = string(file1);
                        savedFiles(end+1,1) = string(file2);
                end
                
                msgbox(sprintf('Images saved successfully:\n%s', strjoin(savedFiles, newline)), ...
                    'Save images');
                
            catch ME
                uialert(app.UIFigure, ...
                    sprintf('Cannot save images:\n%s', ME.message), ...
                    'Save image error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Axis 1')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Axis 2')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.DetectionPanel = uipanel(app.GridLayout);
            app.DetectionPanel.Title = 'Detection';
            app.GridLayout3 = uigridlayout(app.DetectionPanel);
            app.MethodsDropDownLabel = uilabel(app.GridLayout3);
            app.MethodsDropDownLabel.Text = 'Methods';
            app.MethodsDropDown = uidropdown(app.GridLayout3);
            app.MethodsDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodsDropDownValueChanged, true);
            app.SettingsButton = uibutton(app.GridLayout3, 'push');
            app.SettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SettingsButtonPushed, true);
            app.SettingsButton.Text = 'Settings';
            app.CosmicspikedetectionButton = uibutton(app.GridLayout3, 'push');
            app.CosmicspikedetectionButton.ButtonPushedFcn = createCallbackFcn(app, @CosmicspikedetectionButtonPushed, true);
            app.CosmicspikedetectionButton.Text = 'Cosmic spike detection';
            app.Panel = uipanel(app.GridLayout3);
            app.GridLayout4 = uigridlayout(app.Panel);
            app.AgreeButton = uibutton(app.GridLayout4, 'push');
            app.AgreeButton.ButtonPushedFcn = createCallbackFcn(app, @AgreeButtonPushed, true);
            app.AgreeButton.Text = 'Agree';
            app.UncertainButton = uibutton(app.GridLayout4, 'push');
            app.UncertainButton.ButtonPushedFcn = createCallbackFcn(app, @UncertainButtonPushed, true);
            app.UncertainButton.Text = 'Uncertain';
            app.RejectButton = uibutton(app.GridLayout4, 'push');
            app.RejectButton.ButtonPushedFcn = createCallbackFcn(app, @RejectButtonPushed, true);
            app.RejectButton.Text = 'Reject';
            app.LockcosmiclistButton = uibutton(app.GridLayout3, 'push');
            app.LockcosmiclistButton.ButtonPushedFcn = createCallbackFcn(app, @LockcosmiclistButtonPushed, true);
            app.LockcosmiclistButton.Text = 'Lock cosmic list';
            app.ResetButton = uibutton(app.GridLayout3, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ScopeDropDownLabel = uilabel(app.GridLayout3);
            app.ScopeDropDownLabel.Text = 'Scope';
            app.ScopeDropDown = uidropdown(app.GridLayout3);
            app.ScopeDropDown.ValueChangedFcn = createCallbackFcn(app, @ScopeDropDownValueChanged, true);
            app.FilterButton = uibutton(app.GridLayout3, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Text = 'Filter';
            app.Tree = uitree(app.GridLayout);
            app.Tree.Multiselect = 'on';
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Node = uitreenode(app.Tree);
            app.Node.Text = 'Node';
            app.Node2 = uitreenode(app.Node);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.Node);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.Node);
            app.Node4.Text = 'Node4';
            app.CorrectionPanel = uipanel(app.GridLayout);
            app.CorrectionPanel.Title = 'Correction';
            app.GridLayout5 = uigridlayout(app.CorrectionPanel);
            app.MethodsDropDown_2Label = uilabel(app.GridLayout5);
            app.MethodsDropDown_2Label.Text = 'Methods';
            app.MethodsDropDown_2 = uidropdown(app.GridLayout5);
            app.MethodsDropDown_2.ValueChangedFcn = createCallbackFcn(app, @MethodsDropDown_2ValueChanged, true);
            app.TrialButton = uibutton(app.GridLayout5, 'push');
            app.TrialButton.ButtonPushedFcn = createCallbackFcn(app, @TrialButtonPushed, true);
            app.TrialButton.Text = 'Trial';
            app.ApplyforallButton = uibutton(app.GridLayout5, 'push');
            app.ApplyforallButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyforallButtonPushed, true);
            app.ApplyforallButton.Text = 'Apply for all';
            app.SettingsButton_2 = uibutton(app.GridLayout5, 'push');
            app.SettingsButton_2.ButtonPushedFcn = createCallbackFcn(app, @SettingsButton_2Pushed, true);
            app.SettingsButton_2.Text = 'Settings';
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.FinishButton = uibutton(app.GridLayout6, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.SaveimagesButton = uibutton(app.GridLayout6, 'push');
            app.SaveimagesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveimagesButtonPushed, true);
            app.SaveimagesButton.Text = 'Save images';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sg_cosmic

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end