classdef sg_define_spec < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        Panel                         matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        SavefiguresButton             matlab.ui.control.Button
        SpectrapeakinformationButton  matlab.ui.control.Button
        PeakdefineButton              matlab.ui.control.Button
        DeletespectrumButton          matlab.ui.control.Button
        SetrangesButton               matlab.ui.control.Button
        GridLayout5                   matlab.ui.container.GridLayout
        FinishButton                  matlab.ui.control.Button
        DatadefineLabel               matlab.ui.control.Label
        Tree                          matlab.ui.container.Tree
        Node                          matlab.ui.container.TreeNode
        Node2                         matlab.ui.container.TreeNode
        Node3                         matlab.ui.container.TreeNode
        Node4                         matlab.ui.container.TreeNode
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Parent app
        mainApp
        
        % Core spectral data
        wn double = []
        spectra double = []
        names string = strings(0,1)
        
        % Spectrum metadata
        reviewMeta table = table()
        groupIDs string = strings(0,1)
        groupLabels string = strings(0,1)
        filePaths string = strings(0,1)
        sourceIndices double = []
        
        % Current view state
        viewMode string = "spectra"
        selectedSpectrumIndex double = []
        selectedGroupID string = ""
        
        % Graphics
        lineHandles = gobjects(0)
        groupNodeHandles = gobjects(0)
        
        % Original state
        originalCount double = 0
        originalNames string = strings(0,1)
        
        % Deleted spectra information
        deletedNames string = strings(0,1)
        deletedGroupIDs string = strings(0,1)
        deletedFilePaths string = strings(0,1)
        deletedSourceIndices double = []
        
        % Spectral definition
        spectralDef struct = struct()
        
        % Peaks
        peakLibrary table = table()
        projectName string = ""
        peakDefinitionDirty logical = false
        
        % Current peak library source
        peakLibraryFile string = ""
    end
    
    methods (Access = public)
        
        function receiveData( ...
                app, ...
                mainApp, ...
                wn, ...
                spectra, ...
                names, ...
                reviewMeta, ...
                spectralDef, ...
                peakLibrary, ...
                projectName)
            
            % =========================================================
            % PARENT
            % =========================================================
            app.mainApp = mainApp;
            
            
            % =========================================================
            % CORE DATA
            % =========================================================
            app.wn = double(wn(:));
            app.spectra = double(spectra);
            app.names = erase(string(names(:)), ".txt");
            
            
            if isempty(app.wn)
                error('WN axis is empty.');
            end
            
            if isempty(app.spectra)
                error('Spectra matrix is empty.');
            end
            
            if size(app.spectra,1) ~= numel(app.wn)
                error('Spectra rows do not match WN axis length.');
            end
            
            
            nSpectra = size(app.spectra,2);
            
            
            if numel(app.names) ~= nSpectra
                error('Spectrum names do not match spectra columns.');
            end
            
            
            % =========================================================
            % METADATA
            % =========================================================
            if ~istable(reviewMeta)
                error('Review metadata must be a table.');
            end
            
            
            requiredVars = [ ...
                "SpectrumName", ...
                "GroupID", ...
                "GroupLabel", ...
                "FilePath", ...
                "SourceIndex"];
            
            
            missingVars = setdiff( ...
                requiredVars, ...
                string(reviewMeta.Properties.VariableNames));
            
            
            if ~isempty(missingVars)
                error( ...
                    'Missing metadata field(s): %s', ...
                    strjoin(missingVars, ', '));
            end
            
            
            if height(reviewMeta) ~= nSpectra
                error('Metadata rows do not match spectra columns.');
            end
            
            
            metaNames = ...
                erase(string(reviewMeta.SpectrumName(:)), ".txt");
            
            
            if any(metaNames ~= app.names)
                error('Metadata spectrum order does not match data order.');
            end
            
            
            app.reviewMeta = reviewMeta;
            
            app.groupIDs = ...
                string(reviewMeta.GroupID(:));
            
            app.groupLabels = ...
                string(reviewMeta.GroupLabel(:));
            
            app.filePaths = ...
                string(reviewMeta.FilePath(:));
            
            app.sourceIndices = ...
                double(reviewMeta.SourceIndex(:));
            
            
            % =========================================================
            % ORIGINAL STATE
            % =========================================================
            app.originalCount = nSpectra;
            
            app.originalNames = app.names;
            
            app.deletedNames = strings(0,1);
            
            app.deletedGroupIDs = strings(0,1);
            
            app.deletedFilePaths = strings(0,1);
            
            app.deletedSourceIndices = [];
            
            
            % =========================================================
            % SPECTRAL DEFINITION
            % =========================================================
            if nargin >= 7 && ...
                    ~isempty(spectralDef) && ...
                    isstruct(spectralDef)
                
                app.spectralDef = spectralDef;
                
            else
                
                app.spectralDef = struct();
                
            end
            
            
            if ~isfield(app.spectralDef,'roiRanges') || ...
                    isempty(app.spectralDef.roiRanges)
                
                app.spectralDef.roiRanges = ...
                    [min(app.wn), max(app.wn)];
                
            end
            
            
            app.updateSpectralMasks();
            
            
            % =========================================================
            % PEAK LIBRARY
            % =========================================================
            if nargin >= 8 && ~isempty(peakLibrary)
                
                app.peakLibrary = peakLibrary;
                
            else
                
                app.peakLibrary = table();
                
            end
            
            
            if nargin >= 9
                
                app.projectName = string(projectName);
                
            else
                
                app.projectName = "";
                
            end
            
            
            % =========================================================
            % INITIAL VIEW
            % =========================================================
            app.viewMode = "spectra";
            
            app.selectedSpectrumIndex = [];
            
            app.selectedGroupID = "";
            
            app.peakDefinitionDirty = false;
            
            
            app.FinishButton.Enable = 'on';
            app.DeletespectrumButton.Enable = 'on';
            app.SavefiguresButton.Enable = 'on';
            app.PeakdefineButton.Enable = 'on';
            app.SpectrapeakinformationButton.Enable = 'on';
            
            
            app.showSpectraView();
            
            
            zoom(app.UIFigure,'off');
            pan(app.UIFigure,'off');
            
        end
        
    end
    
    methods (Access = private)
        
        % Function 0


        % Function 4
        
        
        function plotSpectraByGroup(app)
            
            cla(app.UIAxes, 'reset');
            
            app.UIAxes.Box = 'on';
            
            grid(app.UIAxes, 'off');
            
            
            if isempty(app.spectra)
                title(app.UIAxes, 'No spectra');
                return
            end
            
            
            groups = app.buildCurrentGroups();
            
            co = app.UIAxes.ColorOrder;
            
            nSpectra = size(app.spectra,2);
            
            app.lineHandles = gobjects(nSpectra,1);
            
            
            hold(app.UIAxes, 'on');
            
            
            for g = 1:numel(groups)
                
                idxList = groups(g).Indices;
                
                groupColor = ...
                    co(mod(g-1, size(co,1)) + 1, :);
                
                
                for j = 1:numel(idxList)
                    
                    idx = idxList(j);
                    
                    h = plot( ...
                        app.UIAxes, ...
                        app.wn, ...
                        app.spectra(:,idx), ...
                        'Color', groupColor, ...
                        'LineWidth', 0.65, ...
                        'HitTest', 'on', ...
                        'PickableParts', 'visible', ...
                        'UserData', idx, ...
                        'DisplayName', char(app.names(idx)), ...
                        'ButtonDownFcn', ...
                        @(src,event)app.spectrumLineClicked( ...
                        src, event, idx));
                    
                    app.lineHandles(idx) = h;
                    
                end
                
            end
            
            
            hold(app.UIAxes, 'off');
            
            
            xlabel(app.UIAxes, ...
                'Raman shift (cm^{-1})');
            
            ylabel(app.UIAxes, ...
                'Intensity (a.u.)');
            
            
            title(app.UIAxes, ...
                sprintf( ...
                'Spectra review — %d spectra in %d groups', ...
                nSpectra, numel(groups)));
            
            
            % =========================================================
            % DISPLAY RANGE
            % =========================================================
            if isfield(app.spectralDef, 'roiMask') && ...
                    numel(app.spectralDef.roiMask) == numel(app.wn) && ...
                    any(app.spectralDef.roiMask)
                
                wnDisplay = ...
                    app.wn(app.spectralDef.roiMask);
                
                xlim(app.UIAxes, ...
                    [min(wnDisplay), max(wnDisplay)]);
                
            else
                
                xlim(app.UIAxes, ...
                    [min(app.wn), max(app.wn)]);
                
            end
        end
        
        
        function spectrumLineClicked(app, src, event, idx)
            
            if app.viewMode ~= "spectra"
                return
            end
            
            if idx < 1 || idx > size(app.spectra,2)
                return
            end
            
            
            % Reset line widths according to current selected group.
            if app.selectedGroupID ~= ""
                
                app.highlightGroup(app.selectedGroupID);
                
            else
                
                for k = 1:numel(app.lineHandles)
                    
                    if isvalid(app.lineHandles(k))
                        app.lineHandles(k).LineWidth = 0.65;
                    end
                    
                end
                
            end
            
            
            app.lineHandles(idx).LineWidth = 2.5;
            
            app.selectedSpectrumIndex = idx;
            
            
            cp = app.UIAxes.CurrentPoint;
            
            [~, wnIdx] = ...
                min(abs(app.wn - cp(1,1)));
            
            
            title(app.UIAxes, ...
                sprintf( ...
                '%s | Group: %s | %.2f cm^{-1}', ...
                app.names(idx), ...
                app.groupLabels(idx), ...
                app.wn(wnIdx)), ...
                'Interpreter', 'none');
            
        end
        
        % Function 6
        
        % Function 7:
        
        
        % function updateSpectralMasks(app)
        %     wn = app.wn(:);
        %     roiMask = false(size(wn));
        %     silentMask = false(size(wn));
        %     peakMask = false(size(wn));
        %     excludeMask = false(size(wn));
        %     for k = 1:size(app.spectralDef.roiRanges, 1)
        %         roiMask = roiMask | ...
        %             (wn >= app.spectralDef.roiRanges(k,1) & wn <= app.spectralDef.roiRanges(k,2));
        %     end
        %     for k = 1:size(app.spectralDef.silentRanges, 1)
        %         silentMask = silentMask | ...
        %             (wn >= app.spectralDef.silentRanges(k,1) & wn <= app.spectralDef.silentRanges(k,2));
        %     end
        %     for k = 1:size(app.spectralDef.peakRanges, 1)
        %         peakMask = peakMask | ...
        %             (wn >= app.spectralDef.peakRanges(k,1) & wn <= app.spectralDef.peakRanges(k,2));
        %     end
        %     for k = 1:size(app.spectralDef.excludeRanges, 1)
        %         excludeMask = excludeMask | ...
        %             (wn >= app.spectralDef.excludeRanges(k,1) & wn <= app.spectralDef.excludeRanges(k,2));
        %     end
        %     app.spectralDef.roiMask = roiMask & ~excludeMask;
        %     app.spectralDef.silentMask = silentMask & ~excludeMask;
        %     app.spectralDef.peakMask = peakMask & ~excludeMask;
        %     app.spectralDef.excludeMask = excludeMask;
        % end
        
        function ranges = parseRangeText(app,txt)
            txt = strtrim(string(txt));
            ranges = [];
            if txt == ""
                return
            end
            parts = split(txt,';');
            for k = 1:numel(parts)
                nums = regexp(char(parts(k)),'[-+]?\d*\.?\d+','match');
                if numel(nums) ~= 2
                    ranges = [];
                    return
                end
                a = str2double(nums{1});
                b = str2double(nums{2});
                if isnan(a) || isnan(b) || a == b
                    ranges = [];
                    return
                end
                ranges(end+1,:) = [min(a,b),max(a,b)];
            end
        end
        
        
        function theoryCol = findTheoreticalPeakColumn(app, T)
            
            names = string(T.Properties.VariableNames);
            namesNorm = lower(strtrim(names));
            
            candidates = [
                "theoretical peak"
                "theoreticalpeak"
                "reference band"
                "reference band (cm⁻¹)"
                "referenceband"
                ];
            
            theoryCol = [];
            
            for i = 1:numel(candidates)
                
                idx = find( ...
                    strcmpi(namesNorm, candidates(i)), ...
                    1, ...
                    'first');
                
                if ~isempty(idx)
                    theoryCol = idx;
                    return;
                end
                
            end
            
            error( ...
                ['Cannot find the theoretical peak column in ', ...
                'the Peak library sheet.']);
            
        end


        % function loadPeakLibraryFromExcel(app)
        %     [file, path] = uigetfile( ...
        %         {'*.xlsx;*.xls','Excel files (*.xlsx, *.xls)'}, ...
        %         'Select peak interpretation library');
        %
        %     if isequal(file,0)
        %         return
        %     end
        %
        %     filePath = fullfile(path,file);
        %     T = readtable(filePath, 'VariableNamingRule', 'preserve');
        %
        %     varNames = string(T.Properties.VariableNames);
        %
        %     peakCol = find(contains(lower(varNames), "experimental"), 1);
        %     interpCol = find(contains(lower(varNames), "interpretation"), 1);
        %
        %     if isempty(peakCol) || isempty(interpCol)
        %         uialert(app.UIFigure, ...
        %             'Excel must contain columns: Experimental peaks and Interpretation.', ...
        %             'Invalid peak library');
        %         return
        %     end
        %
        %     rawPeak = string(T{:,peakCol});
        %     rawInterp = string(T{:,interpCol});
        %     ExperimentalPeak = strings(numel(rawPeak),1);
        %     LowWN = nan(numel(rawPeak),1);
        %     HighWN = nan(numel(rawPeak),1);
        %     CenterWN = nan(numel(rawPeak),1);
        %     Interpretation = strings(numel(rawPeak),1);
        %     keep = false(numel(rawPeak),1);
        %     for k = 1:numel(rawPeak)
        %         txt = strtrim(rawPeak(k));
        %         interp = strtrim(rawInterp(k));
        %         if txt == "" || interp == "" || ismissing(txt) || ismissing(interp)
        %             continue
        %         end
        %         nums = regexp(char(txt), '[-+]?\d*\.?\d+', 'match');
        %         nums = str2double(nums);
        %         if isempty(nums) || any(isnan(nums))
        %             continue
        %         end
        %         if numel(nums) == 1
        %             center = nums(1);
        %             low = center - 5;
        %             high = center + 5;
        %         else
        %             low = min(nums(1), nums(2));
        %             high = max(nums(1), nums(2));
        %             center = mean([low high]);
        %         end
        %         keep(k) = true;
        %         ExperimentalPeak(k) = txt;
        %         LowWN(k) = low;
        %         HighWN(k) = high;
        %         CenterWN(k) = center;
        %         Interpretation(k) = interp;
        %     end
        %
        %     app.peakLibrary = table( ...
        %         ExperimentalPeak(keep), ...
        %         LowWN(keep), ...
        %         HighWN(keep), ...
        %         CenterWN(keep), ...
        %         Interpretation(keep), ...
        %         'VariableNames', { ...
        %         'ExperimentalPeak', ...
        %         'LowWN', ...
        %         'HighWN', ...
        %         'CenterWN', ...
        %         'Interpretation'});
        %
        %     if isempty(app.peakLibrary)
        %         uialert(app.UIFigure, ...
        %             'No valid peak rows were found in the Excel file.', ...
        %             'Empty peak library');
        %         return
        %     end
        %     if ~isempty(app.mainApp) && isvalid(app.mainApp)
        %         app.mainApp.receivePeakLibrary(app.peakLibrary);
        %     end
        %     uialert(app.UIFigure, ...
        %         sprintf(['Peak library loaded and sent to Raman app.\n\n' ...
        %         'Valid peaks: %d'], height(app.peakLibrary)), ...
        %         'Peak library loaded');
        % end
        
        
        % function showPeakLibraryTable(app)
        %     if isempty(app.peakLibrary)
        %         app.loadPeakLibraryFromExcel();
        %     end
        %     if isempty(app.peakLibrary)
        %         return
        %     end
        %     fig = uifigure( ...
        %         'Name', 'Peak interpretation library', ...
        %         'Position', [250 150 760 420]);
        %     uitable(fig, ...
        %         'Data', app.peakLibrary, ...
        %         'Position', [20 20 720 380]);
        % end


        % function updateSpectralMasks(app)
        %     wn = app.wn(:);
        %     roiMask = false(size(wn));
        %     if isfield(app.spectralDef, 'roiRanges') && ~isempty(app.spectralDef.roiRanges)
        %         for k = 1:size(app.spectralDef.roiRanges,1)
        %             lo = min(app.spectralDef.roiRanges(k,1), app.spectralDef.roiRanges(k,2));
        %             hi = max(app.spectralDef.roiRanges(k,1), app.spectralDef.roiRanges(k,2));
        %             roiMask = roiMask | (wn >= lo & wn <= hi);
        %         end
        %     else
        %         roiMask = true(size(wn));
        %     end
        %     app.spectralDef.roiMask = roiMask;
        %     app.spectralDef.silentRanges = [];
        %     app.spectralDef.silentMask = false(size(wn));
        %     app.spectralDef.peakRanges = [];
        %     app.spectralDef.peakMask = false(size(wn));
        %     app.spectralDef.excludeRanges = [];
        %     app.spectralDef.excludeMask = false(size(wn));
        % end
        %


        function rebuildReviewMeta(app)
            app.reviewMeta = table( ...
                app.names(:), ...
                app.groupIDs(:), ...
                app.groupLabels(:), ...
                app.filePaths(:), ...
                app.sourceIndices(:), ...
                'VariableNames', { ...
                'SpectrumName', ...
                'GroupID', ...
                'GroupLabel', ...
                'FilePath', ...
                'SourceIndex'});
        end
        
        function groups = buildCurrentGroups(app)
            
            groups = struct( ...
                'ID', {}, ...
                'Label', {}, ...
                'Indices', {}, ...
                'Count', {});
            
            
            if isempty(app.groupIDs)
                return
            end
            
            
            nSpectra = size(app.spectra,2);
            
            
            if numel(app.groupIDs) ~= nSpectra || ...
                    numel(app.groupLabels) ~= nSpectra
                
                error( ...
                    ['Group metadata are not aligned with ' ...
                    'the current spectra matrix.']);
                
            end
            
            
            uniqueIDs = ...
                unique(app.groupIDs(:), 'stable');
            
            
            for g = 1:numel(uniqueIDs)
                
                groupID = uniqueIDs(g);
                
                idx = find(app.groupIDs == groupID);
                
                
                groups(g).ID = ...
                    groupID;
                
                
                groups(g).Label = ...
                    app.groupLabels(idx(1));
                
                
                groups(g).Indices = ...
                    idx(:)';
                
                
                groups(g).Count = ...
                    numel(idx);
                
            end
            
        end
        
        function populateGroupTree(app)
            
            delete(app.Tree.Children);
            
            groups = app.buildCurrentGroups();
            
            root = uitreenode(app.Tree);
            
            root.Text = sprintf( ...
                'Spectra groups (%d groups, %d spectra)', ...
                numel(groups), ...
                size(app.spectra,2));
            
            root.NodeData = struct( ...
                'Type', "root", ...
                'GroupID', "");
            
            
            app.groupNodeHandles = ...
                gobjects(numel(groups),1);
            
            
            for g = 1:numel(groups)
                
                node = uitreenode(root);
                
                node.Text = sprintf( ...
                    '%s (%d)', ...
                    groups(g).Label, ...
                    groups(g).Count);
                
                node.NodeData = struct( ...
                    'Type', "group", ...
                    'GroupID', groups(g).ID);
                
                app.groupNodeHandles(g) = node;
                
            end
            
            expand(root);
        end
        
        
        function showSpectraView(app)
            app.viewMode = "spectra";
            app.SpectrapeakinformationButton.Text = ...
                'Show peaks';
            app.DeletespectrumButton.Enable = 'on';
            app.populateGroupTree();
            app.plotSpectraByGroup();
            app.selectedSpectrumIndex = [];
            app.selectedGroupID = "";
        end
        
        function highlightGroup(app, groupID)
            if app.viewMode ~= "spectra"
                return
            end
            groupID = string(groupID);
            app.selectedGroupID = groupID;
            for k = 1:numel(app.lineHandles)
                if ~isvalid(app.lineHandles(k))
                    continue
                end
                if app.groupIDs(k) == groupID
                    app.lineHandles(k).LineWidth = 1.25;
                else
                    app.lineHandles(k).LineWidth = 0.35;
                end
            end
            
            mask = app.groupIDs == groupID;
            title(app.UIAxes, ...
                sprintf( ...
                '%s — %d spectra', ...
                groupID, ...
                nnz(mask)), ...
                'Interpreter', 'none');
        end
        
        
        function [centerWN, lowWN, highWN] = parsePeakText(app, value)
            
            centerWN = NaN;
            lowWN = NaN;
            highWN = NaN;
            
            
            % =========================================================
            % CONVERT INPUT TO TEXT
            % =========================================================
            txt = strtrim(string(value));
            
            
            if ismissing(txt) || txt == ""
                return
            end
            
            
            % =========================================================
            % EXTRACT POSITIVE NUMBERS
            %
            % Examples:
            %   "812"       -> 812
            %   "430-450"   -> 430, 450
            %   "430–450"   -> 430, 450
            %   "430 — 450" -> 430, 450
            %
            % Raman shift values are treated as positive values here.
            % =========================================================
            numsText = regexp( ...
                char(txt), ...
                '\d+(?:\.\d+)?', ...
                'match');
            
            
            if isempty(numsText)
                return
            end
            
            
            nums = str2double(numsText);
            
            nums = nums(isfinite(nums));
            
            
            if isempty(nums)
                return
            end
            
            
            % =========================================================
            % SINGLE THEORETICAL PEAK
            %
            % Example:
            %   812
            %
            % Keep the theoretical value itself here.
            % Search window ±5 is handled in suggestObservedPeaks().
            % =========================================================
            if numel(nums) == 1
                
                centerWN = nums(1);
                
                lowWN = centerWN;
                
                highWN = centerWN;
                
                return
            end
            
            
            % =========================================================
            % THEORETICAL RANGE
            %
            % Example:
            %   430-450
            % =========================================================
            lowWN = min(nums(1), nums(2));
            
            highWN = max(nums(1), nums(2));
            
            centerWN = mean([lowWN, highWN]);
            
        end
        
        function peakView = buildPeakViewTable(app)
            
            peakView = table();
            
            if isempty(app.peakLibrary) || ...
                    ~istable(app.peakLibrary)
                
                return
            end
            
            T = ...
                app.peakLibrary;
            
            [theoryCol, meaningCol] = ...
                app.findPeakLibraryColumns(T);
            
            if isempty(theoryCol) || ...
                    isempty(meaningCol)
                
                return
            end
            
            n = height(T);
            
            % =========================================================
            % THEORY / INTERPRETATION
            % =========================================================
            TheoryText = ...
                string(T{:,theoryCol});
            
            Interpretation = ...
                string(T{:,meaningCol});
            
            TheoryText(ismissing(TheoryText)) = "";
            Interpretation(ismissing(Interpretation)) = "";
            
            TheoryText = ...
                strtrim(TheoryText);
            
            Interpretation = ...
                strtrim(Interpretation);
            
            TheoryCenter = ...
                NaN(n,1);
            
            TheoryLow = ...
                NaN(n,1);
            
            TheoryHigh = ...
                NaN(n,1);
            
            for k = 1:n
                
                [ ...
                    TheoryCenter(k), ...
                    TheoryLow(k), ...
                    TheoryHigh(k)] = ...
                    app.parsePeakText(TheoryText(k));
                
            end
            
            % =========================================================
            % OBSERVED PEAKS
            %
            % DO NOT search project columns in Peak library anymore.
            % Observed positions belong to the current project state/history.
            % =========================================================
            ObservedWN = ...
                NaN(n,1);
            
            if isfield(app.spectralDef, 'peakObservedWN') && ...
                    ~isempty(app.spectralDef.peakObservedWN)
                
                candidate = ...
                    double(app.spectralDef.peakObservedWN(:));
                
                if numel(candidate) == n
                    
                    ObservedWN = ...
                        candidate;
                    
                end
                
            end
            
            % =========================================================
            % VALID LIBRARY ROWS
            % =========================================================
            keep = ...
                isfinite(TheoryCenter) & ...
                Interpretation ~= "";
            
            peakView = table( ...
                find(keep), ...
                TheoryText(keep), ...
                TheoryCenter(keep), ...
                TheoryLow(keep), ...
                TheoryHigh(keep), ...
                ObservedWN(keep), ...
                Interpretation(keep), ...
                'VariableNames', { ...
                'LibraryRow', ...
                'TheoryText', ...
                'TheoryCenter', ...
                'TheoryLow', ...
                'TheoryHigh', ...
                'ObservedWN', ...
                'Interpretation'});
            
        end
        
        
        function populatePeakTree(app)
            
            delete(app.Tree.Children);
            
            
            root = uitreenode(app.Tree);
            
            root.Text = 'Peaks';
            
            root.NodeData = struct( ...
                'Type', "root", ...
                'PeakRow', NaN);
            
            
            peakView = ...
                app.buildPeakViewTable();
            
            
            if isempty(peakView)
                
                node = uitreenode(root);
                
                node.Text = ...
                    'No peak definition available';
                
                node.NodeData = struct( ...
                    'Type', "empty", ...
                    'PeakRow', NaN);
                
                expand(root);
                
                return
                
            end
            
            
            % =========================================================
            % PEAK NODES
            % =========================================================
            for k = 1:height(peakView)
                
                node = uitreenode(root);
                
                
                % -----------------------------------------------------
                % Display observed position when available.
                % Otherwise use theoretical center.
                % -----------------------------------------------------
                if isfinite(peakView.ObservedWN(k))
                    
                    displayCenter = ...
                        peakView.ObservedWN(k);
                    
                else
                    
                    displayCenter = ...
                        peakView.TheoryCenter(k);
                    
                end
                
                
                % -----------------------------------------------------
                % Short tree text:
                % 1 - 438 - ν2PO4
                % -----------------------------------------------------
                node.Text = sprintf( ...
                    '%d - %.1f - %s', ...
                    k, ...
                    displayCenter, ...
                    char(peakView.Interpretation(k)));
                
                
                node.NodeData = struct( ...
                    'Type', "peak", ...
                    'PeakRow', k);
                
            end
            
            
            expand(root);
            
        end


        function showPeakView(app)
            
            app.viewMode = "peaks";
            
            
            app.SpectrapeakinformationButton.Text = ...
                'Show spectra';
            
            
            app.DeletespectrumButton.Enable = 'off';
            
            
            app.selectedSpectrumIndex = [];
            
            
            app.populatePeakTree();
            
            app.plotPeakDefinitionView();
            
            
            if app.peakDefinitionDirty
                
                title(app.UIAxes, ...
                    ['Peak definition — red: theoretical | ' ...
                    'blue: observed | REVIEW RECOMMENDED'], ...
                    'Interpreter', 'none');
                
            end
            
        end
        
        
        function updateSpectralMasks(app)
            wn = app.wn(:);
            roiMask = false(size(wn));
            if isfield(app.spectralDef, 'roiRanges') && ...
                    ~isempty(app.spectralDef.roiRanges)
                ranges = app.spectralDef.roiRanges;
                for k = 1:size(ranges,1)
                    lo = min(ranges(k,:));
                    hi = max(ranges(k,:));
                    roiMask = ...
                        roiMask | ...
                        (wn >= lo & wn <= hi);
                end
            else
                roiMask(:) = true;
                app.spectralDef.roiRanges = ...
                    [min(wn), max(wn)];
            end
            app.spectralDef.roiMask = roiMask;
        end
        
        function [theoryCol, meaningCol] = ...
                findPeakLibraryColumns(app, T)
            
            theoryCol = [];
            meaningCol = [];
            
            if isempty(T) || ~istable(T)
                return
            end
            
            vars = string(T.Properties.VariableNames);
            
            % Normalize header:
            % "Theoretical peak" -> "theoreticalpeak"
            % "Interpretation note" -> "interpretationnote"
            varsNorm = lower( ...
                regexprep( ...
                strtrim(vars), ...
                '[^a-zA-Z0-9]', ...
                ''));
            
            % =========================================================
            % THEORETICAL PEAK
            % =========================================================
            theoryCol = find( ...
                varsNorm == "theoreticalpeak", ...
                1, ...
                'first');
            
            % Backward-compatible fallbacks
            if isempty(theoryCol)
                
                theoryCol = find( ...
                    contains(varsNorm, "theoreticalpeak") | ...
                    contains(varsNorm, "referenceband"), ...
                    1, ...
                    'first');
                
            end
            
            % =========================================================
            % INTERPRETATION NOTE
            % =========================================================
            meaningCol = find( ...
                varsNorm == "interpretationnote", ...
                1, ...
                'first');
            
            if isempty(meaningCol)
                
                meaningCol = find( ...
                    contains(varsNorm, "interpretation") | ...
                    contains(varsNorm, "meaning"), ...
                    1, ...
                    'first');
                
            end
            
        end


        function [found, observedWN] = ...
                readProjectPeakHistory( ...
                app, ...
                filePath, ...
                T)
            
            found = false;
            observedWN = NaN(height(T),1);
            
            if string(filePath) == "" || ...
                    isempty(T) || ...
                    app.projectName == ""
                
                return
            end
            
            historySheet = ...
                "Peak definition history";
            
            % =========================================================
            % CHECK SHEET
            % =========================================================
            try
                
                sheets = ...
                    string(sheetnames(char(filePath)));
                
            catch
                
                return
            end
            
            idxSheet = find( ...
                strcmpi(strtrim(sheets), historySheet), ...
                1, ...
                'first');
            
            if isempty(idxSheet)
                return
            end
            
            actualSheet = ...
                sheets(idxSheet);
            
            % =========================================================
            % READ HISTORY
            % =========================================================
            try
                
                H = readtable( ...
                    char(filePath), ...
                    'Sheet', ...
                    char(actualSheet), ...
                    'VariableNamingRule', ...
                    'preserve', ...
                    'TextType', ...
                    'string');
                
            catch
                
                return
            end
            
            if isempty(H)
                return
            end
            
            % =========================================================
            % REQUIRED VARIABLES
            % =========================================================
            vars = ...
                string(H.Properties.VariableNames);
            
            varsNorm = lower( ...
                regexprep( ...
                strtrim(vars), ...
                '[^a-zA-Z0-9]', ...
                ''));
            
            projectCol = ...
                find(varsNorm == "project", 1);
            
            peakIDCol = ...
                find(varsNorm == "peakid", 1);
            
            theoryColHistory = ...
                find(varsNorm == "theoreticalpeak", 1);
            
            observedCol = ...
                find( ...
                varsNorm == "observedwn" | ...
                varsNorm == "observedpeak", ...
                1);
            
            if isempty(projectCol) || ...
                    isempty(observedCol)
                
                return
            end
            
            projects = ...
                strtrim(string(H{:,projectCol}));
            
            projectMask = ...
                strcmpi( ...
                projects, ...
                strtrim(app.projectName));
            
            if ~any(projectMask)
                return
            end
            
            H = H(projectMask,:);
            
            % =========================================================
            % MAP HISTORY BACK TO CURRENT PEAK LIBRARY
            % =========================================================
            [libraryTheoryCol, ~] = ...
                app.findPeakLibraryColumns(T);
            
            libraryTheory = ...
                strtrim(string(T{:,libraryTheoryCol}));
            
            % Preferred mapping:
            % TheoreticalPeak text -> current library row.
            if ~isempty(theoryColHistory)
                
                historyTheory = ...
                    strtrim(string(H{:,theoryColHistory}));
                
                rawObserved = ...
                    H{:,observedCol};
                
                for r = 1:height(H)
                    
                    idx = find( ...
                        strcmpi( ...
                        libraryTheory, ...
                        historyTheory(r)), ...
                        1, ...
                        'first');
                    
                    if isempty(idx)
                        continue
                    end
                    
                    val = ...
                        str2double(string(rawObserved(r)));
                    
                    if isfinite(val)
                        observedWN(idx) = val;
                    end
                    
                end
                
                % Fallback for older history:
                % PeakID -> row number.
            elseif ~isempty(peakIDCol)
                
                rawPeakID = ...
                    H{:,peakIDCol};
                
                rawObserved = ...
                    H{:,observedCol};
                
                for r = 1:height(H)
                    
                    peakID = ...
                        str2double(string(rawPeakID(r)));
                    
                    val = ...
                        str2double(string(rawObserved(r)));
                    
                    if isfinite(peakID) && ...
                            peakID >= 1 && ...
                            peakID <= height(T) && ...
                            isfinite(val)
                        
                        observedWN(round(peakID)) = val;
                        
                    end
                    
                end
                
            end
            
            found = any(isfinite(observedWN));
            
        end
        
        
        function [T, filePath, ok] = ...
                readPeakLibraryForDefinition(app)
            
            T = table();
            filePath = "";
            ok = false;
            
            % =========================================================
            % DEFAULT LIBRARY FOLDER
            % =========================================================
            libraryFolder = ...
                'E:\PROJECTS\RamanCube\Data input\library';
            
            if ~isfolder(libraryFolder)
                libraryFolder = pwd;
            end
            
            % =========================================================
            % SELECT EXCEL FILE
            % =========================================================
            [file, path] = uigetfile( ...
                {'*.xlsx;*.xls', ...
                'Excel files (*.xlsx, *.xls)'}, ...
                'Select peak library', ...
                libraryFolder);
            
            if isequal(file,0)
                return
            end
            
            filePath = ...
                string(fullfile(path,file));
            
            % =========================================================
            % VERIFY REQUIRED SHEET
            % =========================================================
            try
                
                sheets = ...
                    string(sheetnames(char(filePath)));
                
            catch ME
                
                uialert( ...
                    app.UIFigure, ...
                    ME.message, ...
                    'Excel read error');
                
                return
            end
            
            peakSheet = "Peak library";
            
            idxSheet = find( ...
                strcmpi(strtrim(sheets), peakSheet), ...
                1, ...
                'first');
            
            if isempty(idxSheet)
                
                uialert( ...
                    app.UIFigure, ...
                    ['The Excel file must contain a sheet named:' newline ...
                    '"Peak library"'], ...
                    'Peak library missing');
                
                return
            end
            
            actualPeakSheet = sheets(idxSheet);
            
            % =========================================================
            % IMPORT OPTIONS FOR PEAK LIBRARY SHEET ONLY
            % =========================================================
            try
                
                opts = detectImportOptions( ...
                    char(filePath), ...
                    'Sheet', ...
                    char(actualPeakSheet), ...
                    'VariableNamingRule', ...
                    'preserve');
                
                % Everything as string so ranges such as 487-504 remain intact.
                for k = 1:numel(opts.VariableNames)
                    
                    try
                        opts = setvartype( ...
                            opts, ...
                            opts.VariableNames{k}, ...
                            'string');
                    catch
                    end
                    
                end
                
                T = readtable( ...
                    char(filePath), ...
                    opts, ...
                    'Sheet', ...
                    char(actualPeakSheet));
                
            catch ME
                
                uialert( ...
                    app.UIFigure, ...
                    ME.message, ...
                    'Peak library read error');
                
                T = table();
                filePath = "";
                
                return
            end
            
            % =========================================================
            % NORMALIZE MISSING STRING VALUES
            % =========================================================
            for k = 1:width(T)
                
                varName = ...
                    T.Properties.VariableNames{k};
                
                if isstring(T.(varName))
                    
                    missingMask = ...
                        ismissing(T.(varName));
                    
                    T.(varName)(missingMask) = "";
                    
                end
            end
            
            % Preserve Excel names but remove accidental outer spaces.
            T.Properties.VariableNames = ...
                cellstr(strtrim( ...
                string(T.Properties.VariableNames)));
            
            % =========================================================
            % REQUIRED COLUMNS
            % =========================================================
            [theoryCol, meaningCol] = ...
                app.findPeakLibraryColumns(T);
            
            if isempty(theoryCol)
                
                uialert( ...
                    app.UIFigure, ...
                    ['Sheet "Peak library" must contain ' ...
                    'a "Theoretical peak" column.'], ...
                    'Invalid peak library');
                
                T = table();
                filePath = "";
                
                return
            end
            
            if isempty(meaningCol)
                
                uialert( ...
                    app.UIFigure, ...
                    ['Sheet "Peak library" must contain ' ...
                    'an "Interpretation note" column.'], ...
                    'Invalid peak library');
                
                T = table();
                filePath = "";
                
                return
            end
            
            % =========================================================
            % REMOVE INVALID LIBRARY ROWS
            % =========================================================
            theoryText = ...
                strtrim(string(T{:,theoryCol}));
            
            meaningText = ...
                strtrim(string(T{:,meaningCol}));
            
            theoryText(ismissing(theoryText)) = "";
            meaningText(ismissing(meaningText)) = "";
            
            keep = ...
                theoryText ~= "" & ...
                meaningText ~= "";
            
            T = T(keep,:);
            
            if isempty(T)
                
                uialert( ...
                    app.UIFigure, ...
                    'No valid peak rows were found in Peak library.', ...
                    'Empty peak library');
                
                filePath = "";
                return
            end
            
            ok = true;
            
        end


        function observedWN = suggestObservedPeaks(app, T)
            
            observedWN = NaN(height(T),1);
            
            
            if isempty(T) || ...
                    isempty(app.spectra) || ...
                    isempty(app.wn)
                
                return
            end
            
            
            [theoryCol, ~] = ...
                app.findPeakLibraryColumns(T);
            
            
            if isempty(theoryCol)
                return
            end
            
            
            % =========================================================
            % REPRESENTATIVE SPECTRUM
            % =========================================================
            meanSpectrum = ...
                mean(app.spectra, 2, 'omitnan');
            
            
            theoryValues = ...
                string(T{:,theoryCol});
            
            
            % =========================================================
            % PEAK-BY-PEAK SEARCH
            % =========================================================
            for k = 1:height(T)
                
                [centerWN, lowWN, highWN] = ...
                    app.parsePeakText(theoryValues(k));
                
                
                if ~isfinite(centerWN)
                    continue
                end
                
                
                % =====================================================
                % SINGLE THEORY PEAK
                % Example:
                % 812 -> search 807–817
                % =====================================================
                if lowWN == highWN
                    
                    searchLow = centerWN - 5;
                    
                    searchHigh = centerWN + 5;
                    
                    
                    % =====================================================
                    % THEORY RANGE
                    % Example:
                    % 430–450 -> search 430–450
                    % =====================================================
                else
                    
                    searchLow = lowWN;
                    
                    searchHigh = highWN;
                    
                end
                
                
                % =====================================================
                % SEARCH MASK
                % =====================================================
                idx = ...
                    app.wn >= searchLow & ...
                    app.wn <= searchHigh;
                
                
                if ~any(idx)
                    continue
                end
                
                
                wnLocal = ...
                    app.wn(idx);
                
                
                yLocal = ...
                    meanSpectrum(idx);
                
                
                if isempty(yLocal) || ...
                        all(~isfinite(yLocal))
                    
                    continue
                end
                
                
                % =====================================================
                % INITIAL OBSERVED PEAK SUGGESTION
                % =====================================================
                [~, localIdx] = ...
                    max(yLocal, [], 'omitnan');
                
                
                observedWN(k) = ...
                    wnLocal(localIdx);
                
            end
            
        end


        function [confirmed, observedWN] = ...
                openPeakDefinitionEditor( ...
                app, ...
                T, ...
                initialObservedWN)
            
            confirmed = false;
            
            
            % =========================================================
            % FIND LIBRARY COLUMNS
            % =========================================================
            [theoryCol, meaningCol] = ...
                app.findPeakLibraryColumns(T);
            
            
            if isempty(theoryCol) || isempty(meaningCol)
                
                observedWN = initialObservedWN(:);
                
                return
            end
            
            
            % =========================================================
            % CLEAN THEORY / INTERPRETATION TEXT
            % =========================================================
            theoryText = ...
                string(T{:,theoryCol});
            
            meaningText = ...
                string(T{:,meaningCol});
            
            
            % Prevent <missing> string values from breaking sprintf.
            theoryText(ismissing(theoryText)) = "";
            
            meaningText(ismissing(meaningText)) = "";
            
            
            theoryText = strtrim(theoryText);
            
            meaningText = strtrim(meaningText);
            
            
            observedWN = ...
                double(initialObservedWN(:));
            
            
            nPeaks = height(T);
            
            
            if numel(observedWN) ~= nPeaks
                
                observedWN = ...
                    NaN(nPeaks,1);
                
            end
            
            
            % =========================================================
            % MEAN SPECTRUM
            % =========================================================
            meanSpectrum = ...
                mean(app.spectra, 2, 'omitnan');
            
            
            % =========================================================
            % EDIT TABLE DATA
            % =========================================================
            editData = table( ...
                theoryText, ...
                observedWN, ...
                meaningText, ...
                'VariableNames', { ...
                'Theoretical', ...
                'Observed', ...
                'Interpretation'});
            
            
            % =========================================================
            % LOCAL STATE
            % =========================================================
            selectedRow = 1;
            
            draggingObservedLine = false;
            
            
            % =========================================================
            % FIGURE
            % =========================================================
            fig = uifigure( ...
                'Name', ...
                'Peak definition', ...
                'Position', ...
                [100 80 1200 680]);
            
            
            mainGrid = uigridlayout( ...
                fig, ...
                [2 2]);
            
            
            mainGrid.RowHeight = ...
                {'1x', 50};
            
            
            mainGrid.ColumnWidth = ...
                {'1x', 420};
            
            
            % =========================================================
            % AXES
            % =========================================================
            ax = uiaxes(mainGrid);
            
            ax.Layout.Row = 1;
            
            ax.Layout.Column = 1;
            
            ax.Box = 'on';
            
            grid(ax,'off');
            
            
            % =========================================================
            % TABLE
            % =========================================================
            tbl = uitable(mainGrid);
            
            tbl.Layout.Row = 1;
            
            tbl.Layout.Column = 2;
            
            tbl.Data = editData;
            
            
            tbl.ColumnEditable = ...
                [false true false];
            
            
            tbl.ColumnWidth = ...
                {110 90 200};
            
            
            % =========================================================
            % STATUS
            % =========================================================
            statusLabel = uilabel(mainGrid);
            
            statusLabel.Layout.Row = 2;
            
            statusLabel.Layout.Column = 1;
            
            statusLabel.Text = ...
                ['Select a peak row. Edit Observed directly, ' ...
                'or drag the active red line on the graph.'];
            
            
            % =========================================================
            % BUTTON GRID
            % =========================================================
            buttonGrid = uigridlayout( ...
                mainGrid, ...
                [1 2]);
            
            
            buttonGrid.Layout.Row = 2;
            
            buttonGrid.Layout.Column = 2;
            
            
            cancelButton = uibutton( ...
                buttonGrid, ...
                'push', ...
                'Text', ...
                'Cancel');
            
            
            confirmButton = uibutton( ...
                buttonGrid, ...
                'push', ...
                'Text', ...
                'Confirm');
            
            
            % =========================================================
            % CALLBACKS
            % =========================================================
            tbl.CellSelectionCallback = ...
                @tableSelectionChanged;
            
            
            tbl.CellEditCallback = ...
                @tableEdited;
            
            
            cancelButton.ButtonPushedFcn = ...
                @cancelClicked;
            
            
            confirmButton.ButtonPushedFcn = ...
                @confirmClicked;
            
            
            fig.WindowButtonMotionFcn = ...
                @mouseMoving;
            
            
            fig.WindowButtonUpFcn = ...
                @stopDragging;
            
            
            % =========================================================
            % FIRST DRAW
            % =========================================================
            redrawPeakEditor();
            
            
            uiwait(fig);
            
            
            if isvalid(fig)
                delete(fig);
            end
            
            
            % =========================================================
            % TABLE SELECTION
            % =========================================================
            function tableSelectionChanged(~, event)
                
                if isempty(event.Indices)
                    return
                end
                
                row = event.Indices(1,1);
                
                if row < 1 || row > nPeaks
                    return
                end
                
                selectedRow = row;
                
                
                % =====================================================
                % SAFE TEXT
                % =====================================================
                theoryLabel = theoryText(selectedRow);
                meaningLabel = meaningText(selectedRow);
                
                if ismissing(theoryLabel) || strlength(theoryLabel) == 0
                    theoryLabel = "N/A";
                end
                
                if ismissing(meaningLabel) || strlength(meaningLabel) == 0
                    meaningLabel = "Unknown";
                end
                
                
                % =====================================================
                % UPDATE STATUS
                % =====================================================
                statusLabel.Text = sprintf( ...
                    'Selected peak %d | %s | Theory: %s cm^{-1}', ...
                    selectedRow, ...
                    char(meaningLabel), ...
                    char(theoryLabel));
                
                
                % =====================================================
                % REDRAW ACTIVE PEAK
                % =====================================================
                redrawPeakEditor();
                
                
                % =====================================================
                % ZOOM
                % =====================================================
                zoomSelectedPeak();
                
            end
            
            
            % =========================================================
            % MANUAL TABLE EDIT
            % =========================================================
            function tableEdited(~, event)
                
                row = event.Indices(1);
                
                col = event.Indices(2);
                
                
                if col ~= 2
                    return
                end
                
                
                val = ...
                    str2double(string(event.NewData));
                
                
                if ~isfinite(val)
                    
                    editData.Observed(row) = ...
                        observedWN(row);
                    
                    tbl.Data = editData;
                    
                    return
                end
                
                
                % Snap to nearest WN sample.
                [~, idxNearest] = ...
                    min(abs(app.wn - val));
                
                
                observedWN(row) = ...
                    app.wn(idxNearest);
                
                
                editData.Observed(row) = ...
                    observedWN(row);
                
                
                tbl.Data = editData;
                
                
                selectedRow = row;
                
                
                redrawPeakEditor();
                
                
                statusLabel.Text = sprintf( ...
                    '%s | Observed: %.2f cm^{-1}', ...
                    meaningText(row), ...
                    observedWN(row));
                
            end
            
            
            % =========================================================
            % START DRAGGING ACTIVE OBSERVED LINE
            % =========================================================
            function startDragging(~,~)
                
                if selectedRow < 1 || ...
                        selectedRow > nPeaks
                    
                    return
                end
                
                
                draggingObservedLine = true;
                
            end
            
            
            % =========================================================
            % DRAG LINE
            % =========================================================
            function mouseMoving(~,~)
                
                if ~draggingObservedLine
                    return
                end
                
                
                cp = ax.CurrentPoint;
                
                clickedWN = cp(1,1);
                
                
                if clickedWN < min(app.wn) || ...
                        clickedWN > max(app.wn)
                    
                    return
                end
                
                
                % Snap to nearest measured WN.
                [~, idxNearest] = ...
                    min(abs(app.wn - clickedWN));
                
                
                observedWN(selectedRow) = ...
                    app.wn(idxNearest);
                
                
                editData.Observed(selectedRow) = ...
                    observedWN(selectedRow);
                
                
                tbl.Data = editData;
                
                
                redrawPeakEditor();
                
                
                statusLabel.Text = sprintf( ...
                    '%s | Observed: %.2f cm^{-1}', ...
                    meaningText(selectedRow), ...
                    observedWN(selectedRow));
                
            end
            
            
            % =========================================================
            % STOP DRAGGING
            % =========================================================
            function stopDragging(~,~)
                
                draggingObservedLine = false;
                
            end
            
            
            % =========================================================
            % REDRAW PLOT
            % =========================================================
            function redrawPeakEditor()
                
                % =========================================================
                % PRESERVE CURRENT X RANGE ONLY IF IT IS A REAL WN RANGE
                %
                % Default empty axes often return [0 1].
                % Do not preserve that range.
                % =========================================================
                preserveXL = false;
                
                currentXL = [];
                
                
                try
                    
                    candidateXL = xlim(ax);
                    
                    
                    if candidateXL(2) > min(app.wn) && ...
                            candidateXL(1) < max(app.wn) && ...
                            diff(candidateXL) > 1
                        
                        currentXL = candidateXL;
                        
                        preserveXL = true;
                        
                    end
                    
                catch
                    
                    preserveXL = false;
                    
                end
                
                
                % =========================================================
                % RESET AXES
                % =========================================================
                cla(ax,'reset');
                
                ax.Box = 'on';
                
                grid(ax,'off');
                
                
                % =========================================================
                % MEAN SPECTRUM
                % =========================================================
                hMean = plot( ...
                    ax, ...
                    app.wn, ...
                    meanSpectrum, ...
                    'k-', ...
                    'LineWidth', ...
                    1.2, ...
                    'HitTest', ...
                    'off', ...
                    'DisplayName', ...
                    'Mean spectrum');
                
                
                hold(ax,'on');
                
                
                % =========================================================
                % LEGEND HANDLES
                % =========================================================
                hTheoryLegend = gobjects(1);
                
                hObservedLegend = gobjects(1);
                
                
                % =========================================================
                % DRAW PEAK MARKERS
                % =========================================================
                for p = 1:nPeaks
                    
                    [theoryCenter,~,~] = ...
                        app.parsePeakText( ...
                        theoryText(p));
                    
                    
                    % =====================================================
                    % THEORETICAL = RED
                    % FIXED
                    % =====================================================
                    if isfinite(theoryCenter)
                        
                        if ~isgraphics(hTheoryLegend)
                            
                            hTheoryLegend = xline( ...
                                ax, ...
                                theoryCenter, ...
                                'r-', ...
                                'LineWidth', ...
                                1.0, ...
                                'HitTest', ...
                                'off', ...
                                'DisplayName', ...
                                'Theoretical');
                            
                        else
                            
                            xline( ...
                                ax, ...
                                theoryCenter, ...
                                'r-', ...
                                'LineWidth', ...
                                1.0, ...
                                'HitTest', ...
                                'off', ...
                                'HandleVisibility', ...
                                'off');
                            
                        end
                        
                    end
                    
                    
                    % =====================================================
                    % OBSERVED = BLUE
                    % EDITABLE
                    % =====================================================
                    if isfinite(observedWN(p))
                        
                        if p == selectedRow
                            
                            hObserved = xline( ...
                                ax, ...
                                observedWN(p), ...
                                'b-', ...
                                'LineWidth', ...
                                3.0, ...
                                'HitTest', ...
                                'on', ...
                                'PickableParts', ...
                                'visible', ...
                                'DisplayName', ...
                                'Observed');
                            
                            
                            hObserved.ButtonDownFcn = ...
                                @startDragging;
                            
                            
                            hObservedLegend = ...
                                hObserved;
                            
                            
                        else
                            
                            if ~isgraphics(hObservedLegend)
                                
                                hObservedLegend = xline( ...
                                    ax, ...
                                    observedWN(p), ...
                                    'b-', ...
                                    'LineWidth', ...
                                    1.2, ...
                                    'HitTest', ...
                                    'off', ...
                                    'DisplayName', ...
                                    'Observed');
                                
                            else
                                
                                xline( ...
                                    ax, ...
                                    observedWN(p), ...
                                    'b-', ...
                                    'LineWidth', ...
                                    1.2, ...
                                    'HitTest', ...
                                    'off', ...
                                    'HandleVisibility', ...
                                    'off');
                                
                            end
                            
                        end
                        
                    end
                    
                end
                
                
                hold(ax,'off');
                
                
                % =========================================================
                % LABELS
                % =========================================================
                xlabel( ...
                    ax, ...
                    'Raman shift (cm^{-1})');
                
                
                ylabel( ...
                    ax, ...
                    'Mean intensity (a.u.)');
                
                
                title( ...
                    ax, ...
                    'Peak definition');
                
                
                % =========================================================
                % RESTORE OR INITIALIZE X RANGE
                % =========================================================
                if preserveXL
                    
                    xlim(ax,currentXL);
                    
                    
                elseif isfield(app.spectralDef,'roiMask') && ...
                        numel(app.spectralDef.roiMask) == numel(app.wn) && ...
                        any(app.spectralDef.roiMask)
                    
                    wnVisible = ...
                        app.wn(app.spectralDef.roiMask);
                    
                    
                    xlim( ...
                        ax, ...
                        [min(wnVisible), ...
                        max(wnVisible)]);
                    
                    
                else
                    
                    xlim( ...
                        ax, ...
                        [min(app.wn), ...
                        max(app.wn)]);
                    
                end
                
                
                % =========================================================
                % LEGEND
                % =========================================================
                legend( ...
                    ax, ...
                    'show', ...
                    'Location', ...
                    'best');
                
            end
            
            
            % =========================================================
            % ZOOM TO SELECTED PEAK
            % =========================================================
            function zoomSelectedPeak()
                
                [centerWN, lowWN, highWN] = ...
                    app.parsePeakText( ...
                    theoryText(selectedRow));
                
                
                if ~isfinite(centerWN)
                    return
                end
                
                
                % Single peak:
                % show theory ±20 for visual editing.
                if lowWN == highWN
                    
                    x1 = centerWN - 20;
                    
                    x2 = centerWN + 20;
                    
                    
                    % Range peak:
                    % show whole theoretical interval + small padding.
                else
                    
                    pad = ...
                        max(10, 0.25*(highWN-lowWN));
                    
                    x1 = lowWN - pad;
                    
                    x2 = highWN + pad;
                    
                end
                
                
                x1 = ...
                    max(x1,min(app.wn));
                
                
                x2 = ...
                    min(x2,max(app.wn));
                
                
                xlim(ax,[x1 x2]);
                
            end
            
            
            % =========================================================
            % CONFIRM
            % =========================================================
            function confirmClicked(~,~)
                
                observedWN = ...
                    double(editData.Observed(:));
                
                
                confirmed = true;
                
                
                uiresume(fig);
                
            end
            
            
            % =========================================================
            % CANCEL
            % =========================================================
            function cancelClicked(~,~)
                
                confirmed = false;
                
                
                uiresume(fig);
                
            end
            
        end
        
        
        function T = writeProjectPeakColumn( ...
                app, ...
                T, ...
                observedWN, ...
                filePath)
            
            % =========================================================
            % VALIDATION
            % =========================================================
            [theoryCol, ~] = ...
                app.findPeakLibraryColumns(T);
            
            if isempty(theoryCol)
                
                error( ...
                    'Theoretical peaks column was not found.');
                
            end
            
            
            projectName = ...
                string(strtrim(app.projectName));
            
            
            if projectName == ""
                
                error( ...
                    'Project name is empty.');
                
            end
            
            
            if numel(observedWN) ~= height(T)
                
                error( ...
                    'Observed peak count does not match library rows.');
                
            end
            
            
            % =========================================================
            % IMPORTANT
            %
            % DO NOT modify the main peak-library sheet.
            %
            % Peak definitions are stored separately in:
            %   "Peak definition history"
            % =========================================================
            
            sheetName = ...
                'Peak definition history';
            
            
            % =========================================================
            % BUILD CURRENT DEFINITION
            % =========================================================
            nPeaks = height(T);
            
            PeakID = ...
                (1:nPeaks)';
            
            Project = ...
                repmat(projectName, nPeaks, 1);
            
            ObservedWN = ...
                double(observedWN(:));
            
            
            % Keep the theoretical band text for traceability.
            TheoreticalPeak = ...
                string(T{:,theoryCol});
            
            
            DefinedAt = ...
                repmat( ...
                string(datetime( ...
                'now', ...
                'Format', ...
                'yyyy-MM-dd HH:mm:ss')), ...
                nPeaks, ...
                1);
            
            
            currentHistory = table( ...
                Project, ...
                PeakID, ...
                TheoreticalPeak, ...
                ObservedWN, ...
                DefinedAt, ...
                'VariableNames', { ...
                'Project', ...
                'PeakID', ...
                'TheoreticalPeak', ...
                'ObservedWN', ...
                'DefinedAt'});
            
            
            % =========================================================
            % READ EXISTING HISTORY
            % =========================================================
            oldHistory = table();
            
            
            try
                
                sheetNames = ...
                    string(sheetnames(char(filePath)));
                
                
                if any(strcmpi(sheetNames, sheetName))
                    
                    oldHistory = ...
                        readtable( ...
                        char(filePath), ...
                        'Sheet', ...
                        sheetName, ...
                        'VariableNamingRule', ...
                        'preserve');
                    
                end
                
            catch
                
                oldHistory = table();
                
            end
            
            
            % =========================================================
            % NORMALIZE EXISTING HISTORY
            % =========================================================
            if ~isempty(oldHistory)
                
                requiredVars = [ ...
                    "Project", ...
                    "PeakID", ...
                    "TheoreticalPeak", ...
                    "ObservedWN", ...
                    "DefinedAt"];
                
                
                if all(ismember( ...
                        requiredVars, ...
                        string(oldHistory.Properties.VariableNames)))
                    
                    oldHistory.Project = ...
                        string(oldHistory.Project);
                    
                    oldHistory.TheoreticalPeak = ...
                        string(oldHistory.TheoreticalPeak);
                    
                    oldHistory.DefinedAt = ...
                        string(oldHistory.DefinedAt);
                    
                    
                    % =================================================
                    % REMOVE PREVIOUS CURRENT VALUE FOR THIS PROJECT
                    %
                    % This keeps ONE active definition per project.
                    % =================================================
                    keep = ...
                        ~strcmpi( ...
                        strtrim(oldHistory.Project), ...
                        projectName);
                    
                    
                    oldHistory = ...
                        oldHistory(keep,:);
                    
                else
                    
                    % Existing sheet has incompatible structure.
                    % Start a clean history table instead of corrupting it.
                    oldHistory = table();
                    
                end
                
            end
            
            
            % =========================================================
            % APPEND CURRENT PROJECT DEFINITION
            % =========================================================
            historyTable = ...
                [oldHistory; currentHistory];
            
            
            % =========================================================
            % WRITE ONLY HISTORY SHEET
            % =========================================================
            writetable( ...
                historyTable, ...
                char(filePath), ...
                'Sheet', ...
                sheetName, ...
                'WriteMode', ...
                'overwritesheet');
            
            
            % =========================================================
            % RETURN MAIN LIBRARY UNCHANGED
            % =========================================================
        end


        function plotPeakDefinitionView(app)
            
            cla(app.UIAxes, 'reset');
            
            app.UIAxes.Box = 'on';
            
            grid(app.UIAxes, 'off');
            
            
            if isempty(app.spectra)
                
                title(app.UIAxes, 'No spectra');
                
                return
                
            end
            
            
            % =========================================================
            % MEAN SPECTRUM
            % =========================================================
            meanSpectrum = ...
                mean(app.spectra, 2, 'omitnan');
            
            
            hMean = plot( ...
                app.UIAxes, ...
                app.wn, ...
                meanSpectrum, ...
                'k-', ...
                'LineWidth', ...
                1.2, ...
                'DisplayName', ...
                'Mean spectrum');
            
            
            hold(app.UIAxes, 'on');
            
            
            % =========================================================
            % PEAK DATA
            % =========================================================
            peakView = ...
                app.buildPeakViewTable();
            
            
            % =========================================================
            % LEGEND HANDLES
            %
            % Create only one legend entry for each peak type.
            % =========================================================
            hTheoryLegend = gobjects(1);
            
            hObservedLegend = gobjects(1);
            
            
            % =========================================================
            % Y LIMIT INFORMATION
            % =========================================================
            yMin = ...
                min(meanSpectrum, [], 'omitnan');
            
            yMax = ...
                max(meanSpectrum, [], 'omitnan');
            
            
            yRange = yMax - yMin;
            
            
            if ~isfinite(yRange) || yRange <= 0
                yRange = 1;
            end
            
            
            numberY = ...
                yMax + 0.04*yRange;
            
            
            % =========================================================
            % DRAW PEAKS
            % =========================================================
            for k = 1:height(peakView)
                
                theoryX = ...
                    peakView.TheoryCenter(k);
                
                
                observedX = ...
                    peakView.ObservedWN(k);
                
                
                % =====================================================
                % THEORETICAL = RED
                % =====================================================
                if isfinite(theoryX) && ...
                        theoryX >= min(app.wn) && ...
                        theoryX <= max(app.wn)
                    
                    if ~isgraphics(hTheoryLegend)
                        
                        hTheoryLegend = xline( ...
                            app.UIAxes, ...
                            theoryX, ...
                            'r-', ...
                            'LineWidth', ...
                            1.0, ...
                            'DisplayName', ...
                            'Theoretical');
                        
                    else
                        
                        xline( ...
                            app.UIAxes, ...
                            theoryX, ...
                            'r-', ...
                            'LineWidth', ...
                            1.0, ...
                            'HandleVisibility', ...
                            'off');
                        
                    end
                    
                end
                
                
                % =====================================================
                % OBSERVED = BLUE
                % =====================================================
                if isfinite(observedX) && ...
                        observedX >= min(app.wn) && ...
                        observedX <= max(app.wn)
                    
                    if ~isgraphics(hObservedLegend)
                        
                        hObservedLegend = xline( ...
                            app.UIAxes, ...
                            observedX, ...
                            'b-', ...
                            'LineWidth', ...
                            1.4, ...
                            'DisplayName', ...
                            'Observed');
                        
                    else
                        
                        xline( ...
                            app.UIAxes, ...
                            observedX, ...
                            'b-', ...
                            'LineWidth', ...
                            1.4, ...
                            'HandleVisibility', ...
                            'off');
                        
                    end
                    
                end
                
                
                % =====================================================
                % NUMBER POSITION
                % =====================================================
                if isfinite(observedX)
                    
                    labelX = observedX;
                    
                elseif isfinite(theoryX)
                    
                    labelX = theoryX;
                    
                else
                    
                    continue
                    
                end
                
                
                % =====================================================
                % PEAK NUMBER
                % =====================================================
                text( ...
                    app.UIAxes, ...
                    labelX, ...
                    numberY, ...
                    sprintf('%d',k), ...
                    'HorizontalAlignment', ...
                    'center', ...
                    'VerticalAlignment', ...
                    'bottom', ...
                    'FontSize', ...
                    9, ...
                    'FontWeight', ...
                    'bold', ...
                    'Interpreter', ...
                    'none');
                
            end
            
            
            hold(app.UIAxes, 'off');
            
            
            % =========================================================
            % LABELS
            % =========================================================
            xlabel( ...
                app.UIAxes, ...
                'Raman shift (cm^{-1})');
            
            
            ylabel( ...
                app.UIAxes, ...
                'Mean intensity (a.u.)');
            
            
            title( ...
                app.UIAxes, ...
                'Peak definition');
            
            
            % =========================================================
            % DISPLAY RANGE
            % =========================================================
            if isfield(app.spectralDef,'roiMask') && ...
                    numel(app.spectralDef.roiMask) == numel(app.wn) && ...
                    any(app.spectralDef.roiMask)
                
                wnDisplay = ...
                    app.wn(app.spectralDef.roiMask);
                
                
                xlim( ...
                    app.UIAxes, ...
                    [min(wnDisplay), ...
                    max(wnDisplay)]);
                
            else
                
                xlim( ...
                    app.UIAxes, ...
                    [min(app.wn), ...
                    max(app.wn)]);
                
            end
            
            
            % =========================================================
            % EXTRA SPACE FOR NUMBERS
            % =========================================================
            ylim( ...
                app.UIAxes, ...
                [ ...
                yMin - 0.05*yRange, ...
                yMax + 0.12*yRange ...
                ]);
            
            
            % =========================================================
            % LEGEND
            % =========================================================
            legend( ...
                app.UIAxes, ...
                'show', ...
                'Location', ...
                'best');
            
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            % =========================================================
            % PREVENT REPEATED FINISH CLICKS
            % =========================================================
            try
                app.FinishButton.Enable = 'off';
            catch
            end
            
            finishSuccessful = false;
            
            try
                % =====================================================
                % 1. VALIDATE CURRENT SPECTRA
                % =====================================================
                if isempty(app.spectra)
                    
                    uialert( ...
                        app.UIFigure, ...
                        'No spectra remain in the dataset.', ...
                        'Finish error');
                    
                    return
                end
                
                if isempty(app.wn)
                    
                    uialert( ...
                        app.UIFigure, ...
                        'The wavenumber axis is empty.', ...
                        'Finish error');
                    
                    return
                end
                
                app.wn = double(app.wn(:));
                
                if size(app.spectra, 1) ~= numel(app.wn)
                    
                    uialert( ...
                        app.UIFigure, ...
                        ['The number of spectrum rows does not match ' ...
                        'the wavenumber axis length.'], ...
                        'Data dimension error');
                    
                    return
                end
                
                % =====================================================
                % 2. VALIDATE PARENT APP
                % =====================================================
                if isempty(app.mainApp)
                    
                    uialert( ...
                        app.UIFigure, ...
                        'Parent app is unavailable.', ...
                        'Finish error');
                    
                    return
                end
                
                try
                    parentIsValid = isvalid(app.mainApp);
                catch
                    parentIsValid = false;
                end
                
                if ~parentIsValid
                    
                    uialert( ...
                        app.UIFigure, ...
                        'Parent app is no longer valid.', ...
                        'Finish error');
                    
                    return
                end
                
                % =====================================================
                % 3. NORMALIZE METADATA SHAPES
                % =====================================================
                app.names = erase(string(app.names(:)), ".txt");
                app.groupIDs = string(app.groupIDs(:));
                app.groupLabels = string(app.groupLabels(:));
                app.filePaths = string(app.filePaths(:));
                app.sourceIndices = app.sourceIndices(:);
                
                nSpectra = size(app.spectra, 2);
                
                % =====================================================
                % 4. VALIDATE METADATA ALIGNMENT
                % =====================================================
                if numel(app.names) ~= nSpectra || ...
                        numel(app.groupIDs) ~= nSpectra || ...
                        numel(app.groupLabels) ~= nSpectra || ...
                        numel(app.filePaths) ~= nSpectra || ...
                        numel(app.sourceIndices) ~= nSpectra
                    
                    uialert( ...
                        app.UIFigure, ...
                        ['Spectral data and metadata are not aligned.' newline ...
                        'Please review deleted or edited spectra.'], ...
                        'Metadata error');
                    
                    return
                end
                
                % =====================================================
                % 5. REBUILD AUTHORITATIVE REVIEW METADATA
                % =====================================================
                app.rebuildReviewMeta();
                
                if ~istable(app.reviewMeta) || ...
                        height(app.reviewMeta) ~= nSpectra
                    
                    uialert( ...
                        app.UIFigure, ...
                        ['The rebuilt metadata table does not match ' ...
                        'the number of spectra.'], ...
                        'Metadata error');
                    
                    return
                end
                
                requiredVariables = [ ...
                    "SpectrumName", ...
                    "GroupID", ...
                    "GroupLabel", ...
                    "FilePath", ...
                    "SourceIndex"];
                
                missingVariables = setdiff( ...
                    requiredVariables, ...
                    string(app.reviewMeta.Properties.VariableNames));
                
                if ~isempty(missingVariables)
                    
                    uialert( ...
                        app.UIFigure, ...
                        "Metadata missing: " + ...
                        strjoin(missingVariables, ", "), ...
                        'Metadata error');
                    
                    return
                end
                
                % =====================================================
                % 6. VALIDATE SPECTRAL DEFINITION
                % =====================================================
                if isempty(app.spectralDef)
                    app.spectralDef = struct();
                end
                
                if ~isstruct(app.spectralDef)
                    
                    uialert( ...
                        app.UIFigure, ...
                        'The spectral definition must be a structure.', ...
                        'Definition error');
                    
                    return
                end
                
                % =====================================================
                % 7. VALIDATE PEAK LIBRARY
                % =====================================================
                if isempty(app.peakLibrary)
                    app.peakLibrary = table();
                end
                
                if ~istable(app.peakLibrary)
                    
                    uialert( ...
                        app.UIFigure, ...
                        'The peak library must be a table.', ...
                        'Peak library error');
                    
                    return
                end
                
                % =====================================================
                % 8. VERIFY PARENT RECEIVER
                % =====================================================
                if ~ismethod(app.mainApp, 'receiveReviewDefinedData')
                    
                    uialert( ...
                        app.UIFigure, ...
                        ['The parent app does not implement ' ...
                        'receiveReviewDefinedData().'], ...
                        'Connection error');
                    
                    return
                end
                
                % =====================================================
                % 9. RETURN REVIEWED DATA TO SINGLE_MODE
                % =====================================================
                app.mainApp.receiveReviewDefinedData( ...
                    app.wn, ...
                    app.spectra, ...
                    app.names, ...
                    app.reviewMeta, ...
                    app.spectralDef, ...
                    app.peakLibrary);
                
                % =====================================================
                % 10. SAVE PARENT CHECKPOINT
                % =====================================================
                if ismethod(app.mainApp, 'saveCheckpointFromChild')
                    app.mainApp.saveCheckpointFromChild( ...
                        "Define spectra");
                end
                
                finishSuccessful = true;
                
            catch ME
                
                reportText = getReport( ...
                    ME, ...
                    'extended', ...
                    'hyperlinks', ...
                    'off');
                
                disp(reportText);
                
                try
                    if isvalid(app.UIFigure)
                        
                        app.UIFigure.Visible = 'on';
                        drawnow;
                        
                        uialert( ...
                            app.UIFigure, ...
                            reportText, ...
                            'Finish error');
                    end
                catch
                    errordlg( ...
                        reportText, ...
                        'Finish error');
                end
            end
            
            % =========================================================
            % 11. CLOSE ONLY AFTER SUCCESSFUL RETURN AND SAVE
            % =========================================================
            if finishSuccessful
                try
                    if isvalid(app.UIFigure)
                        delete(app.UIFigure);
                    end
                catch
                end
            else
                try
                    if isvalid(app.UIFigure)
                        app.FinishButton.Enable = 'on';
                    end
                catch
                end
            end
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            nodes = app.Tree.SelectedNodes;
            
            if isempty(nodes)
                return
            end
            
            
            nd = nodes(1).NodeData;
            
            
            if isempty(nd) || ~isstruct(nd) || ~isfield(nd, 'Type')
                return
            end
            
            
            switch string(nd.Type)
                
                % =====================================================
                % GROUP MODE
                % =====================================================
                case "group"
                    
                    if app.viewMode ~= "spectra"
                        return
                    end
                    
                    app.highlightGroup(string(nd.GroupID));
                    
                    
                    % =====================================================
                    % PEAK MODE
                    % =====================================================
                case "peak"
                    
                    if app.viewMode ~= "peaks"
                        return
                    end
                    
                    peakView = app.buildPeakViewTable();
                    
                    row = nd.PeakRow;
                    
                    if row < 1 || row > height(peakView)
                        return
                    end
                    
                    
                    center = peakView.TheoryCenter(row);
                    
                    lo = peakView.TheoryLow(row);
                    
                    hi = peakView.TheoryHigh(row);
                    
                    
                    if lo == hi
                        
                        halfWidth = max( ...
                            20, ...
                            0.03 * range(app.wn));
                        
                        x1 = center - halfWidth;
                        
                        x2 = center + halfWidth;
                        
                    else
                        
                        pad = max(10, 0.5 * (hi-lo));
                        
                        x1 = lo - pad;
                        
                        x2 = hi + pad;
                        
                    end
                    
                    
                    x1 = max(x1, min(app.wn));
                    
                    x2 = min(x2, max(app.wn));
                    
                    
                    xlim(app.UIAxes, [x1 x2]);
                    
                    
                    observed = peakView.ObservedWN(row);
                    
                    
                    title( ...
                        app.UIAxes, ...
                        sprintf( ...
                        'Peak %d | %s', ...
                        row, ...
                        char(peakView.Interpretation(row))), ...
                        'Interpreter', ...
                        'none');
                    
            end
            
        end

        % Button pushed function: SetrangesButton
        function SetrangesButtonPushed(app, event)
            if isempty(app.wn)
                return
            end
            
            
            currentText = sprintf( ...
                '%.2f %.2f', ...
                min(app.wn), ...
                max(app.wn));
            
            
            if isfield(app.spectralDef, 'roiRanges') && ...
                    ~isempty(app.spectralDef.roiRanges)
                
                parts = strings( ...
                    size(app.spectralDef.roiRanges,1), 1);
                
                for k = 1:size(app.spectralDef.roiRanges,1)
                    
                    parts(k) = sprintf( ...
                        '%.2f %.2f', ...
                        app.spectralDef.roiRanges(k,1), ...
                        app.spectralDef.roiRanges(k,2));
                    
                end
                
                currentText = strjoin(parts, '; ');
                
            end
            
            
            answer = inputdlg( ...
                {['Analysis ranges. ' ...
                'Use "start end" and separate multiple ranges with semicolon:']}, ...
                'Analysis ranges', ...
                [1 70], ...
                {char(currentText)});
            
            
            if isempty(answer)
                return
            end
            
            
            ranges = ...
                app.parseRangeText(answer{1});
            
            
            if isempty(ranges)
                
                uialert( ...
                    app.UIFigure, ...
                    'Invalid range format.', ...
                    'Invalid range');
                
                return
            end
            
            
            % Clip partially overlapping ranges to available WN axis.
            ranges(:,1) = max(ranges(:,1), min(app.wn));
            
            ranges(:,2) = min(ranges(:,2), max(app.wn));
            
            
            valid = ranges(:,1) < ranges(:,2);
            
            ranges = ranges(valid,:);
            
            
            if isempty(ranges)
                
                uialert( ...
                    app.UIFigure, ...
                    'No selected range overlaps the current WN axis.', ...
                    'Invalid range');
                
                return
            end
            
            
            app.spectralDef.roiRanges = ranges;
            
            app.updateSpectralMasks();
            
            
            % No success popup.
            % Just refresh the current view.
            if app.viewMode == "spectra"
                
                app.showSpectraView();
                
            else
                
                app.showPeakView();
                
            end
        end

        % Button pushed function: SpectrapeakinformationButton
        function SpectrapeakinformationButtonPushed(app, event)
            switch app.viewMode
                case "spectra"
                    app.showPeakView();
                case "peaks"
                    app.showSpectraView();
                otherwise
                    app.showSpectraView();
            end
        end

        % Button pushed function: SavefiguresButton
        function SavefiguresButtonPushed(app, event)
            answer = inputdlg( ...
                {'Resolution DPI:'}, ...
                'Save review figure', ...
                [1 40], ...
                {'300'});
            
            if isempty(answer)
                return
            end
            
            
            dpi = str2double(answer{1});
            
            if ~isfinite(dpi) || dpi <= 0
                
                uialert( ...
                    app.UIFigure, ...
                    'DPI must be a positive number.', ...
                    'Invalid DPI');
                
                return
            end
            
            
            switch app.viewMode
                
                case "spectra"
                    defaultName = "spectra_review";
                    
                case "peaks"
                    defaultName = "peak_definition";
                    
                otherwise
                    defaultName = "review_figure";
                    
            end
            
            
            if ~isempty(app.mainApp) && ...
                    isvalid(app.mainApp)
                
                filePath = ...
                    app.mainApp.askImageFilePath( ...
                    "DataReviewDefine", ...
                    defaultName);
                
            else
                
                folderPath = uigetdir( ...
                    '', ...
                    'Select output folder');
                
                if isequal(folderPath,0)
                    return
                end
                
                
                timestamp = char( ...
                    datetime( ...
                    'now', ...
                    'Format', ...
                    'yyyy-MM-dd_HH-mm-ss'));
                
                
                filePath = fullfile( ...
                    folderPath, ...
                    char(defaultName + "_" + timestamp + ".png"));
                
            end
            
            
            if string(filePath) == ""
                return
            end
            
            
            try
                
                exportgraphics( ...
                    app.UIAxes, ...
                    filePath, ...
                    'Resolution', dpi);
                
            catch ME
                
                uialert( ...
                    app.UIFigure, ...
                    ME.message, ...
                    'Save figure error');
                
            end
        end

        % Button pushed function: PeakdefineButton
        function PeakdefineButtonPushed(app, event)
            try
                
                % =========================================================
                % 1. CHECK CURRENT DATA
                % =========================================================
                if isempty(app.spectra) || isempty(app.wn)
                    
                    uialert( ...
                        app.UIFigure, ...
                        'No spectra are available for peak definition.', ...
                        'Peak define');
                    
                    return
                end
                
                if strlength(strtrim(app.projectName)) == 0
                    
                    uialert( ...
                        app.UIFigure, ...
                        'Project name is empty.', ...
                        'Peak define');
                    
                    return
                end
                
                % =========================================================
                % 2. READ PEAK LIBRARY
                % =========================================================
                [T, filePath, ok] = ...
                    app.readPeakLibraryForDefinition();
                
                if ~ok
                    return
                end
                
                app.peakLibraryFile = ...
                    filePath;
                
                % =========================================================
                % 3. CHECK PROJECT HISTORY
                % =========================================================
                [historyFound, historyObservedWN] = ...
                    app.readProjectPeakHistory( ...
                    filePath, ...
                    T);
                
                % =========================================================
                % 4. EXISTING PROJECT DEFINITION
                % =========================================================
                if historyFound
                    
                    choice = uiconfirm( ...
                        app.UIFigure, ...
                        sprintf( ...
                        ['A peak definition already exists for "%s".' newline ...
                        'Use it or redefine the observed peaks?'], ...
                        char(app.projectName)), ...
                        'Existing peak definition', ...
                        'Options', { ...
                        'Use existing', ...
                        'Redefine', ...
                        'Cancel'}, ...
                        'DefaultOption', ...
                        'Use existing', ...
                        'CancelOption', ...
                        'Cancel');
                    
                    switch choice
                        
                        case 'Use existing'
                            
                            app.peakLibrary = T;
                            
                            % Store active observed positions inside app memory.
                            app.spectralDef.peakObservedWN = ...
                                historyObservedWN(:);
                            
                            app.spectralDef.peakDefinitionProject = ...
                                string(app.projectName);
                            
                            app.spectralDef.peakLibraryFile = ...
                                string(filePath);
                            
                            app.peakDefinitionDirty = false;
                            
                            app.showPeakView();
                            
                            return
                            
                        case 'Redefine'
                            
                            initialObservedWN = ...
                                historyObservedWN(:);
                            
                        otherwise
                            
                            return
                            
                    end
                    
                else
                    
                    % =====================================================
                    % 5. NEW PROJECT
                    % =====================================================
                    initialObservedWN = ...
                        app.suggestObservedPeaks(T);
                    
                end
                
                % =========================================================
                % 6. OPEN PEAK EDITOR
                % =========================================================
                [confirmed, observedWN] = ...
                    app.openPeakDefinitionEditor( ...
                    T, ...
                    initialObservedWN);
                
                if ~confirmed
                    return
                end
                
                % =========================================================
                % 7. SAVE TO HISTORY SHEET
                % =========================================================
                T = app.writeProjectPeakColumn( ...
                    T, ...
                    observedWN, ...
                    filePath);
                
                % =========================================================
                % 8. UPDATE APP MEMORY
                % =========================================================
                app.peakLibrary = T;
                
                app.spectralDef.peakObservedWN = ...
                    observedWN(:);
                
                app.spectralDef.peakDefinitionProject = ...
                    string(app.projectName);
                
                app.spectralDef.peakLibraryFile = ...
                    string(filePath);
                
                app.peakDefinitionDirty = false;
                
                app.showPeakView();
                
            catch ME
                
                reportText = getReport( ...
                    ME, ...
                    'extended', ...
                    'hyperlinks', ...
                    'off');
                
                disp(reportText);
                
                uialert( ...
                    app.UIFigure, ...
                    reportText, ...
                    'Peak define error');
                
            end
        end

        % Button pushed function: DeletespectrumButton
        function DeletespectrumButtonPushed(app, event)
            if app.viewMode ~= "spectra"
                return
            end
            
            
            idx = app.selectedSpectrumIndex;
            
            
            if isempty(idx) || ...
                    idx < 1 || ...
                    idx > size(app.spectra,2)
                
                uialert( ...
                    app.UIFigure, ...
                    'Select a spectrum directly on the graph first.', ...
                    'No spectrum selected');
                
                return
            end
            
            
            choice = uiconfirm( ...
                app.UIFigure, ...
                sprintf('Delete spectrum "%s"?', app.names(idx)), ...
                'Confirm delete', ...
                'Options', {'Delete','Cancel'}, ...
                'DefaultOption', 'Cancel');
            
            
            if ~strcmp(choice,'Delete')
                return
            end
            
            
            % =========================================================
            % SAVE DELETED INFORMATION BEFORE REMOVAL
            % =========================================================
            app.deletedNames(end+1,1) = ...
                app.names(idx);
            
            app.deletedGroupIDs(end+1,1) = ...
                app.groupIDs(idx);
            
            app.deletedFilePaths(end+1,1) = ...
                app.filePaths(idx);
            
            app.deletedSourceIndices(end+1,1) = ...
                app.sourceIndices(idx);
            
            
            % =========================================================
            % DELETE ALIGNED DATA
            % =========================================================
            app.spectra(:,idx) = [];
            
            app.names(idx) = [];
            
            app.groupIDs(idx) = [];
            
            app.groupLabels(idx) = [];
            
            app.filePaths(idx) = [];
            
            app.sourceIndices(idx) = [];
            
            
            % =========================================================
            % REBUILD METADATA
            % =========================================================
            app.rebuildReviewMeta();
            
            
            if ~isempty(app.peakLibrary)
                app.peakDefinitionDirty = true;
            end
            
            
            % =========================================================
            % REFRESH
            % =========================================================
            app.selectedSpectrumIndex = [];
            
            app.selectedGroupID = "";
            
            app.showSpectraView();
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Spectral graph')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.Tree = uitree(app.GridLayout);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Node = uitreenode(app.Tree);
            app.Node.Text = 'Node';
            app.Node2 = uitreenode(app.Node);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.Node);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.Node);
            app.Node4.Text = 'Node4';
            app.DatadefineLabel = uilabel(app.GridLayout);
            app.DatadefineLabel.Text = 'Data define';
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.FinishButton = uibutton(app.GridLayout5, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.Panel);
            app.SetrangesButton = uibutton(app.GridLayout3, 'push');
            app.SetrangesButton.ButtonPushedFcn = createCallbackFcn(app, @SetrangesButtonPushed, true);
            app.SetrangesButton.Text = 'Set ranges';
            app.DeletespectrumButton = uibutton(app.GridLayout3, 'push');
            app.DeletespectrumButton.ButtonPushedFcn = createCallbackFcn(app, @DeletespectrumButtonPushed, true);
            app.DeletespectrumButton.Text = 'Delete spectrum';
            app.PeakdefineButton = uibutton(app.GridLayout3, 'push');
            app.PeakdefineButton.ButtonPushedFcn = createCallbackFcn(app, @PeakdefineButtonPushed, true);
            app.PeakdefineButton.Text = 'Peak define';
            app.SpectrapeakinformationButton = uibutton(app.GridLayout3, 'push');
            app.SpectrapeakinformationButton.ButtonPushedFcn = createCallbackFcn(app, @SpectrapeakinformationButtonPushed, true);
            app.SpectrapeakinformationButton.Text = 'Spectra/peak information';
            app.SavefiguresButton = uibutton(app.GridLayout3, 'push');
            app.SavefiguresButton.ButtonPushedFcn = createCallbackFcn(app, @SavefiguresButtonPushed, true);
            app.SavefiguresButton.Text = 'Save figures';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sg_define_spec

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end