classdef sg_ratio < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        GridLayout3                matlab.ui.container.GridLayout
        RationoteButton            matlab.ui.control.Button
        medianvalueCheckBox        matlab.ui.control.CheckBox
        meanvalueCheckBox          matlab.ui.control.CheckBox
        GridLayout2                matlab.ui.container.GridLayout
        InterpretsupportButton     matlab.ui.control.Button
        ApplyButton                matlab.ui.control.Button
        ExcelexportButton          matlab.ui.control.Button
        FormulaButton              matlab.ui.control.Button
        FeaturesDropDown           matlab.ui.control.DropDown
        FeaturesDropDownLabel      matlab.ui.control.Label
        ResetButton                matlab.ui.control.Button
        EditField                  matlab.ui.control.EditField
        FilterButton               matlab.ui.control.Button
        FinishButton               matlab.ui.control.Button
        SaveimagesButton           matlab.ui.control.Button
        AddpeaksfromspectraButton  matlab.ui.control.Button
        UITable2                   matlab.ui.control.Table
        Tree                       matlab.ui.container.Tree
        Node                       matlab.ui.container.TreeNode
        Node2                      matlab.ui.container.TreeNode
        Node3                      matlab.ui.container.TreeNode
        Node4                      matlab.ui.container.TreeNode
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        mainApp
        wn double = []
        X double = []
        compareGroups struct = struct('Name', {}, 'BaseGroupNames', {}, 'Indices', {}, 'Count', {})
        spectrumNames string = strings(0,1)
        peakLibrary table = table()
        spectralDef
        featureType string = "Area"
        groupLabels string = strings(0,1)
        groupNames string = strings(0,1)
        selectedPeakTable table = table()
        featureMatrix double = []
        resultAll table = table()
        resultTop table = table()
        ratioValueMatrix double = []
        lastSelectedRows double = []
        
        % Manual Y-limit entered by user.
        % Empty = automatic display.
        boxYLim double = []
        
        % Robust central-view toggle.
        % This changes DISPLAY ONLY.
        % Statistical calculations always use all valid observations.
        boxZoomCentral logical = false
    end
    
    methods (Access = public)
        
        function receiveData( ...
                app, ...
                mainApp, ...
                wn, ...
                X, ...
                compareGroups, ...
                spectrumNames, ...
                peakLibrary, ...
                spectralDef, ...
                featureType)
            
            % =========================================================
            % Parent app
            % =========================================================
            app.mainApp = mainApp;
            
            
            % =========================================================
            % Core analysis data
            % =========================================================
            app.wn = wn(:);
            
            app.X = X;
            
            app.compareGroups = compareGroups;
            
            app.spectrumNames = ...
                string(spectrumNames(:));
            
            app.spectralDef = spectralDef;
            
            
            % =========================================================
            % Get current project name from parent
            % =========================================================
            projectName = "";
            
            try
                
                projectName = ...
                    string(mainApp.getCurrentProjectName());
                
            catch ME
                
                warning( ...
                    'RamanCube:RatioProjectName', ...
                    'Could not read project name from parent app: %s', ...
                    ME.message);
                
            end
            
            
            % =========================================================
            % Normalize defined peak library for Ratio
            % =========================================================
            normalizedPeakLibrary = ...
                app.normalizePeakLibraryForRatio( ...
                peakLibrary, ...
                projectName);
            
            
            if isempty(normalizedPeakLibrary)
                
                % Keep empty canonical schema
                app.peakLibrary = table( ...
                    strings(0,1), ...
                    zeros(0,1), ...
                    zeros(0,1), ...
                    zeros(0,1), ...
                    strings(0,1), ...
                    strings(0,1), ...
                    'VariableNames', { ...
                    'ExperimentalPeak', ...
                    'LowWN', ...
                    'HighWN', ...
                    'CenterWN', ...
                    'Interpretation', ...
                    'Source'});
                
            else
                
                app.peakLibrary = normalizedPeakLibrary;
                
            end
            
            
            % =========================================================
            % Feature type
            % =========================================================
            if ~isempty(featureType) && ...
                    strlength(string(featureType)) > 0
                
                app.featureType = ...
                    string(strtrim(featureType));
                
            else
                
                app.featureType = "Area";
                
            end
            app.FeaturesDropDown.Value = char(app.featureType);
            app.EditField.Value = "";
            app.boxYLim = [];
            app.lastSelectedRows = [];
            app.buildGroupLabels();
            app.populatePeakTree();
            cla(app.UIAxes, 'reset');
            title(app.UIAxes, 'Select ratio from table to show boxplot');
            box(app.UIAxes, 'on');
            grid(app.UIAxes, 'off');
            app.UITable2.Data = table();
        end
        
    end


    methods (Access = private)
        
        function buildGroupLabels(app)
            nSpectra = size(app.X,2);
            app.groupLabels = strings(nSpectra,1);
            app.groupNames = strings(numel(app.compareGroups),1);
            
            for g = 1:numel(app.compareGroups)
                app.groupNames(g) = string(app.compareGroups(g).Name);
                idx = app.compareGroups(g).Indices(:);
                idx = idx(idx >= 1 & idx <= nSpectra);
                app.groupLabels(idx) = string(app.compareGroups(g).Name);
            end
            
            app.groupNames = app.groupNames(app.groupNames ~= "");
        end
        
        
        function populatePeakTree(app)
            
            delete(app.Tree.Children);
            
            root = uitreenode( ...
                app.Tree, ...
                'Text', ...
                'Defined peaks', ...
                'NodeData', ...
                []);
            
            if isempty(app.peakLibrary) || ...
                    ~istable(app.peakLibrary) || ...
                    height(app.peakLibrary) == 0
                
                root.Text = ...
                    'Defined peaks - none';
                
                expand(root);
                return
            end
            
            T = app.peakLibrary;
            
            requiredVars = [ ...
                "LowWN", ...
                "HighWN", ...
                "CenterWN", ...
                "Interpretation"];
            
            if ~all(ismember( ...
                    requiredVars, ...
                    string(T.Properties.VariableNames)))
                
                root.Text = ...
                    'Defined peaks - invalid peak metadata';
                
                expand(root);
                return
            end
            
            peakCount = 0;
            
            for i = 1:height(T)
                
                centerWN = ...
                    double(T.CenterWN(i));
                
                lowWN = ...
                    double(T.LowWN(i));
                
                highWN = ...
                    double(T.HighWN(i));
                
                interpretation = ...
                    string(T.Interpretation(i));
                
                if ~isfinite(centerWN)
                    continue
                end
                
                if ismissing(interpretation) || ...
                        strlength(strtrim(interpretation)) == 0
                    
                    interpretation = ...
                        "No interpretation";
                    
                end
                
                peakCount = ...
                    peakCount + 1;
                
                if abs(highWN - lowWN) < 0.05
                    
                    wnText = sprintf( ...
                        '%.1f cm^{-1}', ...
                        centerWN);
                    
                else
                    
                    wnText = sprintf( ...
                        '%.1f-%.1f cm^{-1}', ...
                        lowWN, ...
                        highWN);
                    
                end
                
                node = uitreenode( ...
                    root, ...
                    'Text', ...
                    sprintf( ...
                    '%d | %s | %s', ...
                    peakCount, ...
                    wnText, ...
                    char(interpretation)));
                
                node.NodeData = struct( ...
                    'Type', ...
                    "peak", ...
                    'PeakIndex', ...
                    i, ...
                    'CenterWN', ...
                    centerWN, ...
                    'LowWN', ...
                    lowWN, ...
                    'HighWN', ...
                    highWN);
                
            end
            
            root.Text = sprintf( ...
                'Defined peaks (%d)', ...
                peakCount);
            
            expand(root);
            
        end
        
        
        function peakTable = getSelectedPeakTableFromTree(app)
            selectedNodes = app.Tree.SelectedNodes;
            peakIdx = [];
            for k = 1:numel(selectedNodes)
                nd = selectedNodes(k).NodeData;
                if isstruct(nd) && isfield(nd,'Type') && string(nd.Type) == "peak"
                    peakIdx(end+1) = nd.PeakIndex;
                end
            end
            peakIdx = unique(peakIdx, 'stable');
            if isempty(peakIdx)
                peakTable = table();
            else
                peakTable = app.peakLibrary(peakIdx,:);
                peakTable.PeakID = peakIdx(:);
            end
        end
        
        
        function plotMeanSpectraForManualSelection(app, ax)
            cla(ax, 'reset');
            
            plotMask = app.getDefinitionMask();
            wnPlot = app.wn(plotMask);
            XPlot = app.X(plotMask,:);
            
            hold(ax, 'on');
            
            for g = 1:numel(app.groupNames)
                mask = app.groupLabels == app.groupNames(g);
                if any(mask)
                    mu = mean(XPlot(:,mask), 2, 'omitnan');
                    plot(ax, wnPlot, mu, 'LineWidth', 1.3, ...
                        'DisplayName', char(app.groupNames(g)));
                end
            end
            
            hold(ax, 'off');
            box(ax, 'on');
            grid(ax, 'off');
            xlabel(ax, 'Wavenumber (cm^{-1})');
            ylabel(ax, 'Mean intensity');
            title(ax, 'Click peaks. Single click = ±5 cm^{-1}. Press ENTER to finish.');
            legend(ax, 'Location', 'best', 'Interpreter', 'none');
            
            if ~isempty(wnPlot)
                xlim(ax, [min(wnPlot), max(wnPlot)]);
            end
        end
        
        
        function mask = getDefinitionMask(app)
            wn = app.wn(:);
            mask = true(size(wn));
            
            if ~isempty(app.spectralDef) && isfield(app.spectralDef,'roiMask') && ...
                    numel(app.spectralDef.roiMask) == numel(wn) && any(app.spectralDef.roiMask)
                
                mask = app.spectralDef.roiMask(:);
                
            elseif ~isempty(app.spectralDef) && isfield(app.spectralDef,'roiRanges') && ...
                    ~isempty(app.spectralDef.roiRanges)
                
                mask = false(size(wn));
                
                for r = 1:size(app.spectralDef.roiRanges,1)
                    lo = app.spectralDef.roiRanges(r,1);
                    hi = app.spectralDef.roiRanges(r,2);
                    mask = mask | (wn >= lo & wn <= hi);
                end
            end
        end
        
        
        function manualPeaks = selectPeaksOnSeparateFigure(app)
            f = figure( ...
                'Name', 'Choose peaks on spectra', ...
                'Color', 'w', ...
                'NumberTitle', 'off');
            
            ax = axes(f);
            app.plotMeanSpectraForManualSelection(ax);
            
            [xClick, ~] = ginput();
            
            manualPeaks = table();
            
            if isempty(xClick)
                if isvalid(f), close(f); end
                return
            end
            
            xClick = xClick(:);
            n = numel(xClick);
            
            ExperimentalPeak = strings(n,1);
            LowWN = nan(n,1);
            HighWN = nan(n,1);
            CenterWN = nan(n,1);
            Interpretation = strings(n,1);
            Source = repmat( ...
                "Manual - clicked", ...
                n, ...
                1);
            for i = 1:n
                c = xClick(i);
                ExperimentalPeak(i) = sprintf('%.1f', c);
                LowWN(i) = max(min(app.wn), c - 5);
                HighWN(i) = min(max(app.wn), c + 5);
                CenterWN(i) = c;
                Interpretation(i) = sprintf('Manual %.1f cm-1', c);
            end
            
            manualPeaks = table( ...
                ExperimentalPeak, ...
                LowWN, ...
                HighWN, ...
                CenterWN, ...
                Interpretation, ...
                Source, ...
                'VariableNames', { ...
                'ExperimentalPeak', ...
                'LowWN', ...
                'HighWN', ...
                'CenterWN', ...
                'Interpretation', ...
                'Source'});
            
            if isvalid(f), close(f); end
        end
        
        
        function appendManualPeaksToTree(app, manualPeaks)
            if isempty(manualPeaks)
                return
            end
            
            oldSelected = app.Tree.SelectedNodes;
            
            app.peakLibrary = [app.peakLibrary; manualPeaks];
            app.populatePeakTree();
            
            root = app.Tree.Children(1);
            allNodes = root.Children;
            nTotal = numel(allNodes);
            nManual = height(manualPeaks);
            newNodes = allNodes((nTotal-nManual+1):nTotal);
            
            oldPeakIdx = [];
            for k = 1:numel(oldSelected)
                nd = oldSelected(k).NodeData;
                if isstruct(nd) && isfield(nd,'Type') && string(nd.Type) == "peak"
                    oldPeakIdx(end+1) = nd.PeakIndex;
                end
            end
            
            keepNodes = matlab.ui.container.TreeNode.empty;
            for k = 1:numel(oldPeakIdx)
                idx = oldPeakIdx(k);
                if idx >= 1 && idx <= numel(allNodes)
                    keepNodes(end+1) = allNodes(idx);
                end
            end
            
            app.Tree.SelectedNodes = [keepNodes(:); newNodes(:)];
        end
        
        
        function featureMat = computePeakFeatures(app, peakTable)
            
            nPeaks = ...
                height(peakTable);
            
            nSpectra = ...
                size(app.X,2);
            
            
            featureMat = ...
                nan(nSpectra, nPeaks);
            
            
            featureType = ...
                string(strtrim(app.FeaturesDropDown.Value));
            
            
            if featureType == ""
                featureType = "Area";
            end
            
            
            % =========================================================
            % LOOP OVER PEAKS
            % =========================================================
            for p = 1:nPeaks
                
                lo = ...
                    min( ...
                    peakTable.LowWN(p), ...
                    peakTable.HighWN(p));
                
                hi = ...
                    max( ...
                    peakTable.LowWN(p), ...
                    peakTable.HighWN(p));
                
                
                idx = ...
                    app.wn >= lo & ...
                    app.wn <= hi;
                
                
                if nnz(idx) < 2
                    continue
                end
                
                
                switch featureType
                    
                    
                    % =====================================================
                    % EXACT INTENSITY
                    % =====================================================
                    case "Exact intensity"
                        
                        [~, idxCenter] = ...
                            min(abs( ...
                            app.wn - ...
                            peakTable.CenterWN(p)));
                        
                        featureMat(:,p) = ...
                            app.X(idxCenter,:).';


                        % =====================================================
                        % AREA
                        % =====================================================
                    case "Area"
                        
                        wnBand = ...
                            app.wn(idx);
                        
                        XBand = ...
                            app.X(idx,:);
                        
                        
                        % Ensure positive orientation for integration
                        [wnBand, sortIdx] = ...
                            sort(wnBand, 'ascend');
                        
                        XBand = ...
                            XBand(sortIdx,:);
                        
                        
                        featureMat(:,p) = ...
                            trapz( ...
                            wnBand, ...
                            XBand, ...
                            1).';


                        % =====================================================
                        % 1/FWHM
                        % =====================================================
                    case "1/FWHM"
                        
                        wnBand = ...
                            app.wn(idx);
                        
                        XBand = ...
                            app.X(idx,:);
                        
                        
                        for s = 1:nSpectra
                            
                            featureMat(s,p) = ...
                                app.computeInverseFWHM( ...
                                wnBand, ...
                                XBand(:,s));
                            
                        end


                    otherwise
                        
                        error( ...
                            'Unsupported Ratio feature type: %s', ...
                            featureType);
                        
                end
                
            end
            
        end


        function [score, pVal, eff, direction, testName] = scoreOneRatio(app, y, labels)
            y = y(:);
            labels = string(labels(:));
            
            valid = isfinite(y) & labels ~= "";
            y = y(valid);
            labels = labels(valid);
            
            score = 0;
            pVal = NaN;
            eff = 0;
            direction = NaN;
            testName = "";
            
            presentGroups = app.groupNames(arrayfun(@(g) any(labels == g), app.groupNames));
            nGroups = numel(presentGroups);
            
            if nGroups < 2
                return
            end
            
            groupN = zeros(nGroups,1);
            for g = 1:nGroups
                groupN(g) = nnz(labels == presentGroups(g) & isfinite(y));
            end
            
            if any(groupN < 2)
                return
            end
            
            if nGroups == 2
                y1 = y(labels == presentGroups(1));
                y2 = y(labels == presentGroups(2));
                
                try
                    pVal = ranksum(y1, y2);
                catch
                    pVal = NaN;
                end
                
                delta = app.computeCliffsDelta(y1, y2);
                
                direction = delta;
                eff = abs(delta);
                score = eff;
                testName = "Mann-Whitney U + Cliff delta";
                
            else
                try
                    [pVal, tbl] = kruskalwallis(y, categorical(labels), 'off');
                    
                    H = tbl{2,5};
                    N = numel(y);
                    k = nGroups;
                    
                    epsilonSq = (H - k + 1) / max(N - k, eps);
                    epsilonSq = max(0, min(1, epsilonSq));
                    
                    eff = epsilonSq;
                    score = epsilonSq;
                    direction = NaN;
                    testName = "Kruskal-Wallis + epsilon squared";
                catch
                    return
                end
            end
            
            if ~isfinite(score), score = 0; end
            if ~isfinite(eff), eff = 0; end
        end
        
        
        function delta = computeCliffsDelta(app, y1, y2)
            y1 = y1(:);
            y2 = y2(:);
            
            y1 = y1(isfinite(y1));
            y2 = y2(isfinite(y2));
            
            n1 = numel(y1);
            n2 = numel(y2);
            
            if n1 == 0 || n2 == 0
                delta = NaN;
                return
            end
            
            greaterCount = 0;
            lessCount = 0;
            
            for i = 1:n1
                greaterCount = greaterCount + sum(y1(i) > y2);
                lessCount = lessCount + sum(y1(i) < y2);
            end
            
            delta = (greaterCount - lessCount) / (n1 * n2);
        end


        function resetRatioApp(app)
            
            app.Tree.SelectedNodes = [];
            
            app.selectedPeakTable = ...
                table();
            
            app.featureMatrix = [];
            
            app.resultAll = ...
                table();
            
            app.resultTop = ...
                table();
            
            app.ratioValueMatrix = [];
            
            app.lastSelectedRows = [];
            
            app.boxYLim = [];
            
            app.boxZoomCentral = ...
                false;
            
            
            app.EditField.Value = "";
            
            
            app.meanvalueCheckBox.Value = ...
                false;
            
            app.medianvalueCheckBox.Value = ...
                false;
            
            
            if isprop(app, 'ZoomcentralButton')
                
                app.ZoomcentralButton.Text = ...
                    'Zoom central';
                
            end
            
            
            app.UITable2.Data = ...
                table();
            
            
            cla(app.UIAxes, 'reset');
            
            title( ...
                app.UIAxes, ...
                'Box plot');
            
            box(app.UIAxes, 'on');
            
            grid(app.UIAxes, 'off');
            
            
            cla(app.UIAxes2, 'reset');
            
        end
        
        
        function saveCurrentFigure(app)
            
            % =========================================================
            % Ask DPI
            % =========================================================
            answer = inputdlg( ...
                {'Enter image resolution DPI:'}, ...
                'Save Ratio image', ...
                [1 45], ...
                {'300'});
            
            
            if isempty(answer)
                return
            end
            
            
            dpi = str2double(answer{1});
            
            
            if isnan(dpi) || ...
                    ~isfinite(dpi) || ...
                    dpi <= 0
                
                uialert( ...
                    app.UIFigure, ...
                    'DPI must be a positive number.', ...
                    'Invalid DPI');
                
                return
                
            end
            
            
            % =========================================================
            % Publication profile
            % =========================================================
            profile = "double";
            
            
            if ~isempty(app.mainApp) && ...
                    isvalid(app.mainApp)
                
                try
                    
                    profile = ...
                        app.mainApp.choosePublicationProfile();
                    
                catch ME
                    
                    warning( ...
                        'RamanCube:RatioProfileFallback', ...
                        'Could not open publication profile selector: %s', ...
                        ME.message);
                    
                    profile = "double";
                    
                end
                
            end
            
            
            if profile == ""
                return
            end
            
            
            % =========================================================
            % Determine file path
            % =========================================================
            if ~isempty(app.mainApp) && ...
                    isvalid(app.mainApp)
                
                filePath = ...
                    app.mainApp.askImageFilePath( ...
                    "Ratio", ...
                    "ratio_boxplot");
                
            else
                
                folderPath = uigetdir( ...
                    '', ...
                    'Select image folder');
                
                
                if isequal(folderPath, 0)
                    return
                end
                
                
                answerName = inputdlg( ...
                    {'Image file name, without extension:'}, ...
                    'Save Ratio image', ...
                    [1 55], ...
                    {'ratio_boxplot'});
                
                
                if isempty(answerName)
                    return
                end
                
                
                fileName = ...
                    strtrim(string(answerName{1}));
                
                
                if fileName == ""
                    fileName = "ratio_boxplot";
                end
                
                
                fileName = regexprep( ...
                    fileName, ...
                    '[\\/:*?"<>|]', ...
                    '_');
                
                
                timestamp = char( ...
                    datetime( ...
                    'now', ...
                    'Format', ...
                    'yyyy-MM-dd_HH-mm-ss'));
                
                
                filePath = fullfile( ...
                    folderPath, ...
                    char( ...
                    fileName + ...
                    "_" + ...
                    string(timestamp) + ...
                    ".png"));
                
            end
            
            
            if string(filePath) == ""
                return
            end
            
            
            % =========================================================
            % Export
            % =========================================================
            try
                
                if ~isempty(app.mainApp) && ...
                        isvalid(app.mainApp)
                    
                    app.mainApp.exportAxesPublication( ...
                        app.UIAxes, ...
                        filePath, ...
                        dpi, ...
                        profile);
                    
                else
                    
                    % Fallback when Ratio is used independently
                    exportgraphics( ...
                        app.UIAxes, ...
                        filePath, ...
                        'Resolution', ...
                        dpi, ...
                        'BackgroundColor', ...
                        'white');
                    
                end
                
                
                msgbox( ...
                    sprintf( ...
                    'Publication figure saved:\n%s', ...
                    filePath), ...
                    'Save image');
                
                
            catch ME
                
                uialert( ...
                    app.UIFigure, ...
                    sprintf( ...
                    'Cannot save image:\n%s', ...
                    ME.message), ...
                    'Save image error');
                
            end
            
        end
        
        
        function finishRatioApp(app)
            if isempty(app.resultTop)
                delete(app.UIFigure);
                return
            end
            
            choice = questdlg( ...
                'Do you want to save ratio screening results to TXT files?', ...
                'Finish Ratio app', ...
                'Save and close', ...
                'Close only', ...
                'Cancel', ...
                'Save and close');
            
            if isempty(choice) || strcmp(choice,'Cancel')
                return
            end
            
            if strcmp(choice, 'Save and close')
                app.saveRatioResultsToTxt();
            end
            
            delete(app.UIFigure);
        end
        
        function saveRatioResultsToTxt(app)
            
            % =============================================================
            % SAVE RATIO RESULTS
            %
            % Saves:
            %
            % 1. selected_peaks_*.txt
            %       Peak definitions / metadata
            %
            % 2. ratio_statistics_*.txt
            %       Statistical result for every ratio
            %
            % 3. peak_values_by_spectrum_*.txt
            %       ORIGINAL numerator / denominator peak-feature values
            %       BEFORE ratio calculation
            %
            % 4. ratio_values_by_spectrum_*.txt
            %       Ratio values for every spectrum
            %
            % 5. ratio_variable_map_*.txt
            %       Safe TXT variable name -> human-readable ratio name
            %
            % 6. ratio_bundle_*.mat
            %       Complete compact MATLAB backup
            %
            % IMPORTANT:
            %
            % Peak features are stored ONCE per peak, not once per ratio.
            % This prevents unnecessary duplication.
            %
            % =============================================================
            
            
            %% ============================================================
            % 1. VALIDATE RATIO RESULTS
            % =============================================================
            
            if isempty(app.resultAll) || ...
                    isempty(app.ratioValueMatrix)
                
                uialert( ...
                    app.UIFigure, ...
                    'No ratio results are available to save.', ...
                    'No Ratio result');
                
                return
            end
            
            
            %% ============================================================
            % 2. OUTPUT FOLDER
            % =============================================================
            
            if ~isempty(app.mainApp) && ...
                    isvalid(app.mainApp)
                
                outFolder = ...
                    app.mainApp.getProjectSubFolder( ...
                    "Results", ...
                    "Ratio");
                
            else
                
                parentFolder = ...
                    uigetdir( ...
                    '', ...
                    'Select folder to save Ratio results');
                
                
                if isequal(parentFolder,0)
                    return
                end
                
                
                outFolder = ...
                    fullfile( ...
                    parentFolder, ...
                    'Results', ...
                    'Ratio');
                
                
                if ~exist(outFolder,'dir')
                    mkdir(outFolder);
                end
                
            end
            
            
            if strlength(string(outFolder)) == 0
                return
            end
            
            
            %% ============================================================
            % 3. TIMESTAMP
            % =============================================================
            
            timestamp = ...
                char(datetime( ...
                'now', ...
                'Format', ...
                'yyyy-MM-dd_HH-mm-ss'));
            
            
            %% ============================================================
            % 4. NUMBER OF SPECTRA
            % =============================================================
            
            nSpectra = ...
                size(app.ratioValueMatrix,1);
            
            
            %% ============================================================
            % 5. SPECTRUM NAMES
            % =============================================================
            
            if numel(app.spectrumNames) == nSpectra
                
                spectrumNames = ...
                    string(app.spectrumNames(:));
                
            else
                
                spectrumNames = ...
                    "Spectrum_" + ...
                    string((1:nSpectra)');
                
            end
            
            
            %% ============================================================
            % 6. GROUP LABELS
            % =============================================================
            
            if numel(app.groupLabels) == nSpectra
                
                groupLabels = ...
                    string(app.groupLabels(:));
                
            else
                
                groupLabels = ...
                    repmat("",nSpectra,1);
                
            end
            
            
            %% ============================================================
            % 7. SELECTED PEAK METADATA
            % =============================================================
            
            if ~isempty(app.selectedPeakTable)
                
                selectedPeakFile = ...
                    fullfile( ...
                    outFolder, ...
                    ['selected_peaks_' ...
                    timestamp ...
                    '.txt']);
                
                
                writetable( ...
                    app.selectedPeakTable, ...
                    selectedPeakFile, ...
                    'Delimiter', ...
                    '\t');
                
            end
            
            
            %% ============================================================
            % 8. RATIO STATISTICS
            % =============================================================
            
            statisticsFile = ...
                fullfile( ...
                outFolder, ...
                ['ratio_statistics_' ...
                timestamp ...
                '.txt']);
            
            
            writetable( ...
                app.resultAll, ...
                statisticsFile, ...
                'Delimiter', ...
                '\t');
            
            
            %% ============================================================
            % 9. PEAK FEATURE VALUES BEFORE RATIO
            %
            % app.featureMatrix:
            %
            %   rows    = spectra
            %   columns = selected peaks
            %
            % Example:
            %
            % SpectrumName  Group  P1      P2      P3
            % S001          Ctrl   120.5   84.2    55.1
            % S002          Ctrl   118.1   90.3    57.8
            %
            % Then:
            %
            % P1/P3 uses EXACTLY these P1 and P3 values.
            %
            % =============================================================
            
            peakValuesSaved = false;
            
            peakFeatureValues = [];
            peakFeaturePeakIDs = [];
            peakFeatureVariableNames = strings(0,1);
            
            
            canSavePeakValues = ...
                ~isempty(app.featureMatrix) && ...
                ~isempty(app.selectedPeakTable) && ...
                istable(app.selectedPeakTable) && ...
                size(app.featureMatrix,1) == nSpectra && ...
                size(app.featureMatrix,2) == ...
                height(app.selectedPeakTable);
            
            
            if canSavePeakValues
                
                peakFeatureValues = ...
                    double(app.featureMatrix);
                
                
                % ---------------------------------------------------------
                % Determine Peak IDs
                % ---------------------------------------------------------
                
                if ismember( ...
                        'PeakID', ...
                        app.selectedPeakTable.Properties.VariableNames)
                    
                    peakFeaturePeakIDs = ...
                        double(app.selectedPeakTable.PeakID(:));
                    
                else
                    
                    peakFeaturePeakIDs = ...
                        (1:height(app.selectedPeakTable))';
                    
                end
                
                
                % ---------------------------------------------------------
                % Human-readable variable names:
                %
                % P1
                % P2
                % P24
                % ...
                % ---------------------------------------------------------
                
                peakFeatureVariableNames = ...
                    "P" + ...
                    string(peakFeaturePeakIDs);
                
                
                % MATLAB table variable names must be valid and unique.
                safePeakVariableNames = ...
                    matlab.lang.makeValidName( ...
                    cellstr(peakFeatureVariableNames));
                
                
                safePeakVariableNames = ...
                    matlab.lang.makeUniqueStrings( ...
                    safePeakVariableNames);
                
                
                % ---------------------------------------------------------
                % Build peak-value table
                % ---------------------------------------------------------
                
                TpeakValues = ...
                    array2table( ...
                    peakFeatureValues, ...
                    'VariableNames', ...
                    safePeakVariableNames);
                
                
                TpeakValues = ...
                    addvars( ...
                    TpeakValues, ...
                    spectrumNames, ...
                    groupLabels, ...
                    'Before', ...
                    1, ...
                    'NewVariableNames', ...
                    { ...
                    'SpectrumName', ...
                    'Group'});
                
                
                % ---------------------------------------------------------
                % Save
                % ---------------------------------------------------------
                
                peakValuesFile = ...
                    fullfile( ...
                    outFolder, ...
                    ['peak_values_by_spectrum_' ...
                    timestamp ...
                    '.txt']);
                
                
                writetable( ...
                    TpeakValues, ...
                    peakValuesFile, ...
                    'Delimiter', ...
                    '\t');
                
                
                peakValuesSaved = true;
                
            else
                
                warning( ...
                    'RamanCube:RatioPeakValuesNotSaved', ...
                    [ ...
                    'Peak-feature values were not saved because ' ...
                    'featureMatrix does not match selectedPeakTable.' ...
                    ]);
                
            end
            
            
            %% ============================================================
            % 10. RATIO VALUES BY SPECTRUM
            % =============================================================
            
            ratioNames = ...
                string(app.resultAll.RatioName);
            
            
            safeRatioVariableNames = ...
                matlab.lang.makeValidName( ...
                cellstr(ratioNames));
            
            
            safeRatioVariableNames = ...
                matlab.lang.makeUniqueStrings( ...
                safeRatioVariableNames);
            
            
            Tvalues = ...
                array2table( ...
                app.ratioValueMatrix, ...
                'VariableNames', ...
                safeRatioVariableNames);
            
            
            Tvalues = ...
                addvars( ...
                Tvalues, ...
                spectrumNames, ...
                groupLabels, ...
                'Before', ...
                1, ...
                'NewVariableNames', ...
                { ...
                'SpectrumName', ...
                'Group'});
            
            
            ratioValuesFile = ...
                fullfile( ...
                outFolder, ...
                ['ratio_values_by_spectrum_' ...
                timestamp ...
                '.txt']);
            
            
            writetable( ...
                Tvalues, ...
                ratioValuesFile, ...
                'Delimiter', ...
                '\t');
            
            
            %% ============================================================
            % 11. RATIO VARIABLE MAPPING
            %
            % Example:
            %
            % RatioVariable     RatioName
            % P1_P24            P1/P24
            %
            % =============================================================
            
            RatioVariable = ...
                string(safeRatioVariableNames(:));
            
            
            RatioName = ...
                ratioNames(:);
            
            
            Tmap = ...
                table( ...
                RatioVariable, ...
                RatioName);
            
            
            mapFile = ...
                fullfile( ...
                outFolder, ...
                ['ratio_variable_map_' ...
                timestamp ...
                '.txt']);
            
            
            writetable( ...
                Tmap, ...
                mapFile, ...
                'Delimiter', ...
                '\t');
            
            
            %% ============================================================
            % 12. PEAK VARIABLE MAPPING
            %
            % This makes the peak-values file self-describing.
            %
            % Example:
            %
            % PeakVariable  PeakID  CenterWN  LowWN  HighWN  Interpretation
            % P1            1       719.6     ...    ...     ...
            %
            % =============================================================
            
            if peakValuesSaved
                
                nPeaks = ...
                    height(app.selectedPeakTable);
                
                
                PeakVariable = ...
                    peakFeatureVariableNames(:);
                
                
                PeakID = ...
                    peakFeaturePeakIDs(:);
                
                
                PeakCenterWN = ...
                    nan(nPeaks,1);
                
                
                PeakLowWN = ...
                    nan(nPeaks,1);
                
                
                PeakHighWN = ...
                    nan(nPeaks,1);
                
                
                PeakInterpretation = ...
                    strings(nPeaks,1);
                
                
                if ismember( ...
                        'CenterWN', ...
                        app.selectedPeakTable.Properties.VariableNames)
                    
                    PeakCenterWN = ...
                        double( ...
                        app.selectedPeakTable.CenterWN(:));
                    
                end
                
                
                if ismember( ...
                        'LowWN', ...
                        app.selectedPeakTable.Properties.VariableNames)
                    
                    PeakLowWN = ...
                        double( ...
                        app.selectedPeakTable.LowWN(:));
                    
                end
                
                
                if ismember( ...
                        'HighWN', ...
                        app.selectedPeakTable.Properties.VariableNames)
                    
                    PeakHighWN = ...
                        double( ...
                        app.selectedPeakTable.HighWN(:));
                    
                end
                
                
                if ismember( ...
                        'Interpretation', ...
                        app.selectedPeakTable.Properties.VariableNames)
                    
                    PeakInterpretation = ...
                        string( ...
                        app.selectedPeakTable.Interpretation(:));
                    
                end
                
                
                TpeakMap = ...
                    table( ...
                    PeakVariable, ...
                    PeakID, ...
                    PeakLowWN, ...
                    PeakHighWN, ...
                    PeakCenterWN, ...
                    PeakInterpretation);
                
                
                peakMapFile = ...
                    fullfile( ...
                    outFolder, ...
                    ['peak_variable_map_' ...
                    timestamp ...
                    '.txt']);
                
                
                writetable( ...
                    TpeakMap, ...
                    peakMapFile, ...
                    'Delimiter', ...
                    '\t');
                
            end
            
            
            %% ============================================================
            % 13. FULL MATLAB BUNDLE
            % =============================================================
            
            RatioExport = struct();
            
            
            RatioExport.savedAt = ...
                datetime('now');
            
            
            % -------------------------------------------------------------
            % Peak definitions
            % -------------------------------------------------------------
            
            RatioExport.selectedPeaks = ...
                app.selectedPeakTable;
            
            
            % -------------------------------------------------------------
            % Ratio statistics
            % -------------------------------------------------------------
            
            RatioExport.statistics = ...
                app.resultAll;
            
            
            % -------------------------------------------------------------
            % Ratio values
            % -------------------------------------------------------------
            
            RatioExport.values = ...
                app.ratioValueMatrix;
            
            
            RatioExport.ratioNames = ...
                ratioNames;
            
            
            % -------------------------------------------------------------
            % NEW:
            % Original peak feature values BEFORE ratio
            % -------------------------------------------------------------
            
            if peakValuesSaved
                
                RatioExport.peakFeatureValues = ...
                    peakFeatureValues;
                
                
                RatioExport.peakFeaturePeakIDs = ...
                    peakFeaturePeakIDs;
                
                
                RatioExport.peakFeatureVariableNames = ...
                    peakFeatureVariableNames;
                
            else
                
                RatioExport.peakFeatureValues = [];
                RatioExport.peakFeaturePeakIDs = [];
                RatioExport.peakFeatureVariableNames = ...
                    strings(0,1);
                
            end
            
            
            % -------------------------------------------------------------
            % Spectrum metadata
            % -------------------------------------------------------------
            
            RatioExport.spectrumNames = ...
                spectrumNames;
            
            
            RatioExport.groupLabels = ...
                groupLabels;
            
            
            RatioExport.groupNames = ...
                app.groupNames;
            
            
            % -------------------------------------------------------------
            % Spectral / feature provenance
            % -------------------------------------------------------------
            
            RatioExport.wavenumber = ...
                app.wn;
            
            
            RatioExport.featureType = ...
                string(app.FeaturesDropDown.Value);
            
            
            % -------------------------------------------------------------
            % Save bundle
            % -------------------------------------------------------------
            
            matFile = ...
                fullfile( ...
                outFolder, ...
                ['ratio_bundle_' ...
                timestamp ...
                '.mat']);
            
            
            save( ...
                matFile, ...
                'RatioExport', ...
                '-v7.3');
            
            
            %% ============================================================
            % 14. DONE
            % =============================================================
            
            if peakValuesSaved
                
                savedText = sprintf([ ...
                    'Ratio results saved successfully.\n\n' ...
                    'Folder:\n%s\n\n' ...
                    'Saved:\n' ...
                    '- selected peaks\n' ...
                    '- peak values by spectrum BEFORE ratio\n' ...
                    '- peak variable mapping\n' ...
                    '- ratio statistics\n' ...
                    '- ratio values by spectrum\n' ...
                    '- ratio variable mapping\n' ...
                    '- full MAT backup'], ...
                    outFolder);
                
            else
                
                savedText = sprintf([ ...
                    'Ratio results saved successfully.\n\n' ...
                    'Folder:\n%s\n\n' ...
                    'Saved:\n' ...
                    '- selected peaks\n' ...
                    '- ratio statistics\n' ...
                    '- ratio values by spectrum\n' ...
                    '- ratio variable mapping\n' ...
                    '- full MAT backup\n\n' ...
                    'WARNING:\n' ...
                    'Peak values could not be saved because the current ' ...
                    'feature matrix was not aligned with selected peaks.'], ...
                    outFolder);
                
            end
            
            
            msgbox( ...
                savedText, ...
                'Ratio saved');
            
        end
        
        function drawRatioLegendOnAxis2(app, ratioNames)
            cla(app.UIAxes2, 'reset');
            
            app.UIAxes2.Visible = 'on';
            app.UIAxes2.Box = 'on';
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.XColor = [0 0 0];
            app.UIAxes2.YColor = [0 0 0];
            app.UIAxes2.LineWidth = 1.5;
            
            ratioNames = string(ratioNames(:));
            ratioNames = ratioNames(ratioNames ~= "");
            
            title(app.UIAxes2, 'Legend', ...
                'FontSize', 15, ...
                'FontWeight', 'bold');
            
            if isempty(ratioNames)
                return
            end
            
            n = numel(ratioNames);
            y = linspace(0.85, 0.15, n);
            
            hold(app.UIAxes2, 'on');
            
            for k = 1:n
                plot(app.UIAxes2, [0.08 0.25], [y(k) y(k)], ...
                    'LineWidth', 5);
                
                text(app.UIAxes2, 0.32, y(k), char(ratioNames(k)), ...
                    'Interpreter', 'none', ...
                    'FontSize', 14, ...
                    'FontWeight', 'bold', ...
                    'VerticalAlignment', 'middle');
            end
            
            hold(app.UIAxes2, 'off');
            
            xlim(app.UIAxes2, [0 1]);
            ylim(app.UIAxes2, [0 1]);
        end
        
        
        function plotSelectedRatioRows(app, selectedRows)
            
            % =========================================================
            % PLOTSELECTEDRATIOROWS
            %
            % Display rules:
            %
            % 1. Box + whisker + scatter points are always displayed.
            %
            % 2. Mean OFF + Median OFF
            %       -> no extra center line / value label.
            %
            % 3. Mean ON
            %       -> dashed mean line + numeric mean value.
            %
            % 4. Median ON
            %       -> solid median line + numeric median value.
            %
            % 5. Mean + Median ON
            %       -> both are displayed.
            %
            % 6. Scatter jitter is deterministic.
            %       Checkbox redraws therefore do NOT move points.
            %
            % 7. Mean / median are calculated from ALL finite values,
            %       regardless of current Y zoom/filter.
            % =========================================================
            
            
            % =========================================================
            % 1. VALIDATE
            % =========================================================
            
            if isempty(app.resultTop) || ...
                    isempty(selectedRows)
                
                return
            end
            
            
            selectedRows = ...
                unique(selectedRows(:)', 'stable');
            
            
            selectedRows = ...
                selectedRows( ...
                selectedRows >= 1 & ...
                selectedRows <= height(app.resultTop));
            
            
            if isempty(selectedRows)
                return
            end
            
            
            app.lastSelectedRows = ...
                selectedRows;
            
            
            % =========================================================
            % 2. RESET AXES
            % =========================================================
            
            cla(app.UIAxes, 'reset');
            cla(app.UIAxes2, 'reset');
            
            
            hold(app.UIAxes, 'on');
            
            
            % =========================================================
            % 3. BASIC INFORMATION
            % =========================================================
            
            groupNames = ...
                string(app.groupNames(:));
            
            
            nGroups = ...
                numel(groupNames);
            
            
            nRatios = ...
                numel(selectedRows);
            
            
            ratioNames = ...
                strings(nRatios,1);
            
            
            % =========================================================
            % 4. BOX POSITIONS
            % =========================================================
            
            if nRatios == 1
                
                offsets = 0;
                
                boxW = 0.35;
                
            else
                
                offsets = ...
                    linspace( ...
                    -0.28, ...
                    0.28, ...
                    nRatios);
                
                boxW = 0.16;
                
            end
            
            
            % =========================================================
            % 5. CHECKBOX STATES
            % =========================================================
            
            showMean = ...
                logical(app.meanvalueCheckBox.Value);
            
            
            showMedian = ...
                logical(app.medianvalueCheckBox.Value);
            
            
            % =========================================================
            % 6. LOOP THROUGH SELECTED RATIOS
            % =========================================================
            
            for k = 1:nRatios
                
                r = ...
                    selectedRows(k);
                
                
                % -----------------------------------------------------
                % Short ratio label:
                % P1/P2, P2/P4, ...
                % -----------------------------------------------------
                
                ratioName = ...
                    string(app.resultTop.RatioName(r));
                
                
                ratioNames(k) = ...
                    ratioName;
                
                
                % -----------------------------------------------------
                % Ratio values for all spectra
                % -----------------------------------------------------
                
                y = ...
                    double(app.ratioValueMatrix(:,r));
                
                
                % =====================================================
                % LOOP THROUGH GROUPS
                % =====================================================
                
                for g = 1:nGroups
                    
                    groupMask = ...
                        app.groupLabels == ...
                        groupNames(g);
                    
                    
                    valid = ...
                        groupMask & ...
                        isfinite(y);
                    
                    
                    yg = ...
                        y(valid);
                    
                    
                    if isempty(yg)
                        continue
                    end
                    
                    
                    yg = ...
                        double(yg(:));
                    
                    
                    x0 = ...
                        g + offsets(k);
                    
                    
                    xBox = ...
                        repmat( ...
                        x0, ...
                        size(yg));
                    
                    
                    % =================================================
                    % 7. BOXCHART
                    %
                    % IMPORTANT:
                    % boxchart intrinsically contains a median line.
                    %
                    % We hide that line visually so Mean / Median are
                    % controlled ONLY by the checkboxes below.
                    % =================================================
                    
                    try
                        
                        b = boxchart( ...
                            app.UIAxes, ...
                            xBox, ...
                            yg, ...
                            'BoxWidth', ...
                            boxW, ...
                            'MarkerStyle', ...
                            'none');
                        
                    catch
                        
                        b = boxchart( ...
                            app.UIAxes, ...
                            xBox, ...
                            yg, ...
                            'BoxWidth', ...
                            boxW);
                        
                    end
                    
                    
                    % -------------------------------------------------
                    % Hide intrinsic median line from boxchart
                    % -------------------------------------------------
                    
                    try
                        
                        b.MedianLineColor = ...
                            b.BoxFaceColor;
                        
                    catch
                        % MATLAB-version compatibility.
                    end
                    
                    
                    % =================================================
                    % 8. DETERMINISTIC JITTER
                    %
                    % Do NOT use rand().
                    %
                    % Otherwise every checkbox click redraws dots at
                    % different X positions and looks like data changed.
                    % =================================================
                    
                    nPoints = ...
                        numel(yg);
                    
                    
                    if nPoints == 1
                        
                        jitter = 0;
                        
                    else
                        
                        % Reproducible pseudo-jitter pattern.
                        %
                        % Different observations receive slightly
                        % different X offsets, but the same observation
                        % always gets the same offset after redraw.
                        
                        indexVector = ...
                            (1:nPoints)';
                        
                        
                        phase = ...
                            mod( ...
                            indexVector * 0.61803398875, ...
                            1);
                        
                        
                        jitter = ...
                            (phase - 0.5) * ...
                            boxW * 0.62;
                        
                    end
                    
                    
                    % =================================================
                    % 9. RAW SCATTER POINTS
                    % =================================================
                    
                    scatter( ...
                        app.UIAxes, ...
                        x0 + jitter, ...
                        yg, ...
                        22, ...
                        'k', ...
                        'filled', ...
                        'MarkerFaceAlpha', ...
                        0.22, ...
                        'MarkerEdgeAlpha', ...
                        0.08, ...
                        'HandleVisibility', ...
                        'off');
                    
                    
                    % =================================================
                    % 10. DESCRIPTIVE STATISTICS
                    %
                    % Always calculated from ALL finite group values.
                    % Zoom / Filter affects display only.
                    % =================================================
                    
                    meanVal = ...
                        mean( ...
                        yg, ...
                        'omitnan');
                    
                    
                    medianVal = ...
                        median( ...
                        yg, ...
                        'omitnan');
                    
                    
                    % =================================================
                    % 11. MEAN
                    %
                    % Dashed line + value
                    % =================================================
                    
                    if showMean && ...
                            isfinite(meanVal)
                        
                        % ---------------------------------------------
                        % Mean horizontal line
                        % ---------------------------------------------
                        
                        plot( ...
                            app.UIAxes, ...
                            [ ...
                            x0 - boxW*0.48, ...
                            x0 + boxW*0.48 ...
                            ], ...
                            [ ...
                            meanVal, ...
                            meanVal ...
                            ], ...
                            '--', ...
                            'LineWidth', ...
                            2.0, ...
                            'HandleVisibility', ...
                            'off');
                        
                        
                        % ---------------------------------------------
                        % Mean numeric value
                        % ---------------------------------------------
                        
                        text( ...
                            app.UIAxes, ...
                            x0 + boxW*0.58, ...
                            meanVal, ...
                            sprintf( ...
                            'mean = %.4g', ...
                            meanVal), ...
                            'FontSize', ...
                            9, ...
                            'FontWeight', ...
                            'normal', ...
                            'HorizontalAlignment', ...
                            'left', ...
                            'VerticalAlignment', ...
                            'middle', ...
                            'Interpreter', ...
                            'none', ...
                            'Clipping', ...
                            'on');
                        
                    end
                    
                    
                    % =================================================
                    % 12. MEDIAN
                    %
                    % Solid line + value
                    % =================================================
                    
                    if showMedian && ...
                            isfinite(medianVal)
                        
                        % ---------------------------------------------
                        % Median horizontal line
                        % ---------------------------------------------
                        
                        plot( ...
                            app.UIAxes, ...
                            [ ...
                            x0 - boxW*0.48, ...
                            x0 + boxW*0.48 ...
                            ], ...
                            [ ...
                            medianVal, ...
                            medianVal ...
                            ], ...
                            '-', ...
                            'LineWidth', ...
                            2.3, ...
                            'HandleVisibility', ...
                            'off');
                        
                        
                        % ---------------------------------------------
                        % Median numeric value
                        % ---------------------------------------------
                        
                        text( ...
                            app.UIAxes, ...
                            x0 - boxW*0.58, ...
                            medianVal, ...
                            sprintf( ...
                            'median = %.4g', ...
                            medianVal), ...
                            'FontSize', ...
                            9, ...
                            'FontWeight', ...
                            'normal', ...
                            'HorizontalAlignment', ...
                            'right', ...
                            'VerticalAlignment', ...
                            'middle', ...
                            'Interpreter', ...
                            'none', ...
                            'Clipping', ...
                            'on');
                        
                    end
                    
                end
                
            end
            
            
            hold(app.UIAxes, 'off');
            
            
            % =========================================================
            % 13. BASIC AXIS FORMAT
            % =========================================================
            
            box(app.UIAxes, 'on');
            
            grid(app.UIAxes, 'off');
            
            
            xlim( ...
                app.UIAxes, ...
                [ ...
                0.5, ...
                nGroups + 0.5 ...
                ]);
            
            
            xticks( ...
                app.UIAxes, ...
                1:nGroups);
            
            
            xticklabels( ...
                app.UIAxes, ...
                cellstr(groupNames));
            
            
            xlabel( ...
                app.UIAxes, ...
                'Group');
            
            
            ylabel( ...
                app.UIAxes, ...
                'Ratio value');
            
            
            % =========================================================
            % 14. MANUAL Y FILTER
            % =========================================================
            
            if ~isempty(app.boxYLim) && ...
                    numel(app.boxYLim) == 2 && ...
                    all(isfinite(app.boxYLim)) && ...
                    app.boxYLim(1) < app.boxYLim(2)
                
                ylim( ...
                    app.UIAxes, ...
                    app.boxYLim);
                
            end
            
            
            % =========================================================
            % 15. TITLE
            % =========================================================
            
            if nRatios == 1
                
                r = ...
                    selectedRows(1);
                
                
                info = ...
                    app.resultTop(r,:);
                
                
                % -----------------------------------------------------
                % Two-group case
                % -----------------------------------------------------
                
                if numel(app.groupNames) == 2
                    
                    titleText = { ...
                        char( ...
                        "Boxplot: " + ...
                        string(info.RatioName)); ...
                        sprintf( ...
                        'p = %.4e | q = %.4e | Cliff delta = %.3f', ...
                        info.PValue, ...
                        info.QValue, ...
                        info.Direction); ...
                        char( ...
                        "Test: " + ...
                        string(info.Test)) ...
                        };
                    
                    
                    % -----------------------------------------------------
                    % More than two groups
                    % -----------------------------------------------------
                    
                else
                    
                    titleText = { ...
                        char( ...
                        "Boxplot: " + ...
                        string(info.RatioName)); ...
                        sprintf( ...
                        'p = %.4e | q = %.4e | epsilon^2 = %.3f', ...
                        info.PValue, ...
                        info.QValue, ...
                        info.EffectSize); ...
                        char( ...
                        "Test: " + ...
                        string(info.Test)) ...
                        };
                    
                end
                
                
                title( ...
                    app.UIAxes, ...
                    titleText, ...
                    'Interpreter', ...
                    'none');
                
                
            else
                
                title( ...
                    app.UIAxes, ...
                    sprintf( ...
                    'Boxplot: %d selected ratios', ...
                    nRatios), ...
                    'Interpreter', ...
                    'none');
                
            end
            
            
            legend( ...
                app.UIAxes, ...
                'off');
            
            
            % =========================================================
            % 16. RATIO LEGEND
            % =========================================================
            
            app.drawRatioLegendOnAxis2( ...
                ratioNames);
            
        end
        
        
        function q = computeBHqValues(app, p)
            p = p(:);
            q = nan(size(p));
            
            valid = isfinite(p) & p >= 0 & p <= 1;
            pv = p(valid);
            
            if isempty(pv)
                return
            end
            
            [ps, order] = sort(pv, 'ascend');
            m = numel(ps);
            
            qs = ps .* m ./ (1:m)';
            qs = flipud(cummin(flipud(qs)));
            qs = min(qs, 1);
            
            qv = nan(size(pv));
            qv(order) = qs;
            
            q(valid) = qv;
        end
        
        
        function manualPeaks = inputManualPeaksByText(app)
            answer = inputdlg( ...
                {'Enter peak center or range. Examples: 785 OR 789 792 OR multiple lines'}, ...
                'Add manual peaks', ...
                [6 55], ...
                {''});
            
            manualPeaks = table();
            
            if isempty(answer)
                return
            end
            
            txt = string(answer{1});
            lines = splitlines(txt);
            lines = strtrim(lines);
            lines = lines(lines ~= "");
            
            if isempty(lines)
                return
            end
            
            ExperimentalPeak = strings(0,1);
            LowWN = [];
            HighWN = [];
            CenterWN = [];
            Interpretation = strings(0,1);
            
            for i = 1:numel(lines)
                nums = sscanf(lines(i), '%f');
                
                if numel(nums) == 1
                    c = nums(1);
                    lo = c - 5;
                    hi = c + 5;
                elseif numel(nums) >= 2
                    lo = min(nums(1), nums(2));
                    hi = max(nums(1), nums(2));
                    c = mean([lo hi]);
                else
                    continue
                end
                
                lo = max(min(app.wn), lo);
                hi = min(max(app.wn), hi);
                
                ExperimentalPeak(end+1,1) = sprintf('%.1f', c);
                LowWN(end+1,1) = lo;
                HighWN(end+1,1) = hi;
                CenterWN(end+1,1) = c;
                Interpretation(end+1,1) = sprintf('Manual %.1f-%.1f cm-1', lo, hi);
            end
            
            Source = repmat( ...
                "Manual - typed", ...
                numel(CenterWN), ...
                1);
            
            manualPeaks = table( ...
                ExperimentalPeak, ...
                LowWN, ...
                HighWN, ...
                CenterWN, ...
                Interpretation, ...
                Source, ...
                'VariableNames', { ...
                'ExperimentalPeak', ...
                'LowWN', ...
                'HighWN', ...
                'CenterWN', ...
                'Interpretation', ...
                'Source'});
        end
        
        
        function runABForAllScreening(app)
            
            % =========================================================
            % 1. SELECTED PEAKS
            % =========================================================
            peakTable = ...
                app.getSelectedPeakTableFromTree();
            
            
            if height(peakTable) < 2
                
                uialert( ...
                    app.UIFigure, ...
                    'Please select at least 2 peaks for a/b screening.', ...
                    'Not enough peaks');
                
                return
            end
            
            
            app.selectedPeakTable = ...
                peakTable;
            
            
            % =========================================================
            % 2. COMPUTE FEATURES
            % =========================================================
            app.featureMatrix = ...
                app.computePeakFeatures( ...
                peakTable);
            
            
            nPeaks = ...
                size(app.featureMatrix,2);
            
            
            % =========================================================
            % 3. RESULT CONTAINERS
            % =========================================================
            RatioName = strings(0,1);
            
            Score = zeros(0,1);
            
            PValue = nan(0,1);
            
            QValue = nan(0,1);
            
            EffectSize = nan(0,1);
            
            Direction = nan(0,1);
            
            Test = strings(0,1);
            
            NumPeak = zeros(0,1);
            
            DenPeak = zeros(0,1);
            
            ValidN = zeros(0,1);
            
            InvalidDenominatorN = zeros(0,1);
            
            
            ratioValues = [];
            
            
            row = 0;
            
            
            % =========================================================
            % 4. DIRECTED RATIOS
            %
            % Test every ordered pair:
            %
            %   P1/P2
            %   P2/P1
            %
            % This is deliberate because zero/negative feature values
            % and denominator filtering can make reciprocal ratios
            % non-equivalent in real preprocessed Raman data.
            % =========================================================
            for i = 1:nPeaks
                
                for j = 1:nPeaks
                    
                    if i == j
                        continue
                    end
                    
                    
                    A = ...
                        double(app.featureMatrix(:,i));
                    
                    B = ...
                        double(app.featureMatrix(:,j));
                    
                    
                    % =================================================
                    % 5. NUMERICAL DENOMINATOR TOLERANCE
                    %
                    % This prevents numerical division by zero.
                    % It is NOT an outlier filter.
                    % =================================================
                    finiteAbsB = ...
                        abs(B(isfinite(B)));
                    
                    
                    finiteAbsB = ...
                        finiteAbsB( ...
                        finiteAbsB > 0);
                    
                    
                    if isempty(finiteAbsB)
                        continue
                    end
                    
                    
                    denScale = ...
                        median( ...
                        finiteAbsB, ...
                        'omitnan');
                    
                    
                    if ~isfinite(denScale) || ...
                            denScale <= 0
                        
                        denScale = ...
                            max(finiteAbsB);
                        
                    end
                    
                    
                    if ~isfinite(denScale) || ...
                            denScale <= 0
                        
                        continue
                    end
                    
                    
                    denominatorTolerance = ...
                        max( ...
                        eps(denScale), ...
                        1e-10 * denScale);
                    
                    
                    % =================================================
                    % 6. RATIO
                    % =================================================
                    y = ...
                        nan(size(A));
                    
                    
                    validDen = ...
                        isfinite(B) & ...
                        abs(B) > denominatorTolerance;
                    
                    
                    validNum = ...
                        isfinite(A);
                    
                    
                    validRatioInput = ...
                        validNum & ...
                        validDen;
                    
                    
                    y(validRatioInput) = ...
                        A(validRatioInput) ./ ...
                        B(validRatioInput);
                    
                    
                    % =================================================
                    % 7. OBSERVATIONS AVAILABLE FOR STATISTICS
                    % =================================================
                    valid = ...
                        isfinite(y) & ...
                        app.groupLabels ~= "";
                    
                    
                    if nnz(valid) < 4
                        continue
                    end
                    
                    
                    % =================================================
                    % 8. STATISTICS
                    % =================================================
                    [ ...
                        score, ...
                        pVal, ...
                        eff, ...
                        direction, ...
                        testName] = ...
                        app.scoreOneRatio( ...
                        y(valid), ...
                        app.groupLabels(valid));
                    
                    
                    if testName == ""
                        continue
                    end
                    
                    
                    % =================================================
                    % 9. STORE
                    % =================================================
                    row = ...
                        row + 1;
                    
                    
                    numID = ...
                        peakTable.PeakID(i);
                    
                    denID = ...
                        peakTable.PeakID(j);
                    
                    
                    RatioName(row,1) = ...
                        sprintf( ...
                        'P%d/P%d', ...
                        numID, ...
                        denID);
                    
                    
                    Score(row,1) = ...
                        score;
                    
                    PValue(row,1) = ...
                        pVal;
                    
                    EffectSize(row,1) = ...
                        eff;
                    
                    Direction(row,1) = ...
                        direction;
                    
                    Test(row,1) = ...
                        testName;
                    
                    NumPeak(row,1) = ...
                        numID;
                    
                    DenPeak(row,1) = ...
                        denID;
                    
                    
                    ValidN(row,1) = ...
                        nnz(valid);
                    
                    
                    InvalidDenominatorN(row,1) = ...
                        nnz( ...
                        isfinite(B) & ...
                        ~validDen);
                    
                    
                    ratioValues(:,row) = ...
                        y;
                    
                end
                
            end
            
            
            % =========================================================
            % 10. NO RESULT
            % =========================================================
            if row == 0
                
                uialert( ...
                    app.UIFigure, ...
                    ['No valid a/b ratio could be generated. ' ...
                    'Check feature values and denominator stability.'], ...
                    'No result');
                
                return
            end
            
            
            % =========================================================
            % 11. BH MULTIPLE-TESTING CORRECTION
            %
            % Correction includes ALL directed ratios tested.
            % =========================================================
            QValue = ...
                app.computeBHqValues( ...
                PValue);
            
            
            % =========================================================
            % 12. COMPLETE RESULT TABLE
            % =========================================================
            resultRaw = ...
                table( ...
                RatioName, ...
                Score, ...
                PValue, ...
                QValue, ...
                EffectSize, ...
                Direction, ...
                Test, ...
                NumPeak, ...
                DenPeak, ...
                ValidN, ...
                InvalidDenominatorN);
            
            
            % =========================================================
            % 13. FINALIZE
            % =========================================================
            app.finalizeRatioResults( ...
                resultRaw, ...
                ratioValues);
            
        end
        
        
        function runFormulaScreening(app)
            peakTableAll = app.peakLibrary;
            if isempty(peakTableAll)
                uialert(app.UIFigure, ...
                    'Peak library is empty. Please add peaks first.', ...
                    'No peaks available');
                return
            end
            peakTableAll.PeakID = (1:height(peakTableAll)).';
            ansFormula = inputdlg( ...
                {'Formula using variables, for example: A/B, (A+B)/C, A/(B+C)'}, ...
                'Formula ratio', ...
                [1 65], ...
                {'A/B'});
            
            if isempty(ansFormula)
                return
            end
            
            formulaText = strtrim(string(ansFormula{1}));
            
            if formulaText == ""
                return
            end
            
            vars = regexp(char(formulaText), '[A-Za-z]\w*', 'match');
            vars = unique(string(vars), 'stable');
            
            if isempty(vars)
                uialert(app.UIFigure, ...
                    'Formula must contain variables such as A, B, C.', ...
                    'Invalid formula');
                return
            end
            
            peakList = strings(height(peakTableAll),1);
            for i = 1:height(peakTableAll)
                peakList(i) = sprintf('P%d: %.1f-%.1f | %s', ...
                    peakTableAll.PeakID(i), ...
                    peakTableAll.LowWN(i), ...
                    peakTableAll.HighWN(i), ...
                    string(peakTableAll.Interpretation(i)));
            end
            
            selectedRows = zeros(numel(vars),1);
            
            for v = 1:numel(vars)
                [idx, ok] = listdlg( ...
                    'PromptString', char("Select peak for variable " + vars(v)), ...
                    'SelectionMode', 'single', ...
                    'ListString', cellstr(peakList), ...
                    'ListSize', [430 300]);
                
                if ~ok || isempty(idx)
                    return
                end
                
                selectedRows(v) = idx;
            end
            
            selectedPeakTable = peakTableAll(selectedRows,:);
            app.selectedPeakTable = selectedPeakTable;
            F = app.computePeakFeatures(selectedPeakTable);
            
            expr = char(formulaText);
            
            if ~isempty(regexp(expr, '[^A-Za-z0-9_\+\-\*\/\.\^\(\)\s]', 'once'))
                uialert(app.UIFigure, ...
                    'Formula contains invalid characters.', ...
                    'Invalid formula');
                return
            end
            
            expr = regexprep(expr, '(?<!\.)\*', '.*');
            expr = regexprep(expr, '(?<!\.)/', './');
            expr = regexprep(expr, '(?<!\.)\^', '.^');
            
            for v = 1:numel(vars)
                eval(sprintf('%s = F(:,%d);', vars(v), v));
            end
            
            try
                y = eval(expr);
            catch
                uialert(app.UIFigure, ...
                    'Cannot evaluate formula. Check the formula syntax.', ...
                    'Formula error');
                return
            end
            
            y = y(:);
            valid = isfinite(y) & app.groupLabels ~= "";
            
            if nnz(valid) < 4
                uialert(app.UIFigure, ...
                    'Formula result has too few valid spectra.', ...
                    'No result');
                return
            end
            
            [score, pVal, eff, direction, testName] = app.scoreOneRatio( ...
                y(valid), app.groupLabels(valid));
            
            if testName == ""
                uialert(app.UIFigure, ...
                    'Cannot compute statistics for this formula.', ...
                    'Statistics error');
                return
            end
            
            RatioName = formulaText;
            Score = score;
            PValue = pVal;
            QValue = pVal;
            EffectSize = eff;
            Direction = direction;
            Test = "Formula: " + testName;
            NumPeak = NaN;
            DenPeak = NaN;
            
            resultRaw = table(RatioName, Score, PValue, QValue, ...
                EffectSize, Direction, Test, NumPeak, DenPeak);
            
            app.finalizeRatioResults(resultRaw, y);
        end
        
        

        function finalizeRatioResults(app, resultRaw, valueMatrix)
            
            % =========================================================
            % FINALIZE RATIO RESULTS
            %
            % UI table:
            %
            % TWO GROUPS
            % Rank | Ratio | p | q | Cliff delta | Effect | Sig.
            %
            % >= 3 GROUPS
            % Rank | Ratio | p | q | epsilon^2 | Effect | Sig.
            %
            % Full peak / wavenumber metadata remain stored in
            % app.resultAll for export and reproducibility.
            % =========================================================
            
            
            % =========================================================
            % 1. VALIDATE
            % =========================================================
            
            if isempty(resultRaw) || ...
                    ~istable(resultRaw)
                
                uialert( ...
                    app.UIFigure, ...
                    'No valid ratio result is available.', ...
                    'No ratio result');
                
                return
            end
            
            
            % =========================================================
            % 2. ATTACH FULL PEAK / WAVENUMBER METADATA
            %
            % Do NOT show these fields in the compact UI table.
            % Keep them for:
            %   - export
            %   - provenance
            %   - reproducibility
            % =========================================================
            
            resultRaw = ...
                app.attachRatioWavenumberMetadata( ...
                resultRaw);
            
            
            % =========================================================
            % 3. PREPARE SORT VARIABLES
            %
            % Ranking priority:
            %
            %   1. q-value ascending
            %   2. effect magnitude descending
            %   3. p-value ascending
            % =========================================================
            
            resultRaw.SortQ = ...
                resultRaw.QValue;
            
            
            resultRaw.SortQ( ...
                ~isfinite(resultRaw.SortQ)) = ...
                inf;
            
            
            resultRaw.SortNegEffect = ...
                -abs(resultRaw.EffectSize);
            
            
            resultRaw.SortP = ...
                resultRaw.PValue;
            
            
            resultRaw.SortP( ...
                ~isfinite(resultRaw.SortP)) = ...
                inf;
            
            
            % =========================================================
            % 4. SORT RESULTS
            % =========================================================
            
            [resultSorted, sortIdx] = ...
                sortrows( ...
                resultRaw, ...
                { ...
                'SortQ', ...
                'SortNegEffect', ...
                'SortP'}, ...
                { ...
                'ascend', ...
                'ascend', ...
                'ascend'});
            
            
            % Remove temporary sort variables
            resultSorted.SortQ = [];
            resultSorted.SortNegEffect = [];
            resultSorted.SortP = [];
            
            
            % =========================================================
            % 5. ADD RANK
            % =========================================================
            
            Rank = ...
                (1:height(resultSorted)).';
            
            
            resultSorted = ...
                addvars( ...
                resultSorted, ...
                Rank, ...
                'Before', ...
                'RatioName');
            
            
            % =========================================================
            % 6. STORE COMPLETE RESULT
            % =========================================================
            
            app.resultAll = ...
                resultSorted;
            
            
            app.resultTop = ...
                app.resultAll;
            
            
            % =========================================================
            % 7. ALIGN RATIO MATRIX WITH SORT ORDER
            %
            % Critical:
            % UITable row r must correspond to ratioValueMatrix(:,r).
            % =========================================================
            
            if isvector(valueMatrix)
                
                valueMatrix = ...
                    valueMatrix(:);
                
            end
            
            
            if size(valueMatrix,2) == numel(sortIdx)
                
                app.ratioValueMatrix = ...
                    valueMatrix(:,sortIdx);
                
                
            elseif numel(sortIdx) == 1 && ...
                    size(valueMatrix,2) == 1
                
                app.ratioValueMatrix = ...
                    valueMatrix;
                
                
            else
                
                error( ...
                    'sg_ratio:RatioMatrixMismatch', ...
                    ['The ratio value matrix does not match ' ...
                    'the number of ratio results.']);
                
            end
            
            
            % =========================================================
            % 8. NUMBER OF GROUPS
            % =========================================================
            
            nGroups = ...
                numel(app.groupNames);
            
            
            % =========================================================
            % 9. EFFECT-SIZE INTERPRETATION
            % =========================================================
            
            effectLevel = ...
                strings( ...
                height(app.resultTop), ...
                1);
            
            
            for r = 1:height(app.resultTop)
                
                effectLevel(r) = ...
                    app.classifyRatioEffect( ...
                    app.resultTop.EffectSize(r), ...
                    nGroups);
                
            end
            
            
            % =========================================================
            % 10. SIGNIFICANCE FLAGS FROM q-VALUE
            %
            % Multiple-testing corrected significance:
            %
            % ***  q < 0.001
            % **   q < 0.01
            % *    q < 0.05
            % ns   otherwise
            % =========================================================
            
            significance = ...
                strings( ...
                height(app.resultTop), ...
                1);
            
            
            for r = 1:height(app.resultTop)
                
                significance(r) = ...
                    app.classifyQValue( ...
                    app.resultTop.QValue(r));
                
            end
            
            
            % =========================================================
            % 11. BUILD COMPACT UI TABLE
            % =========================================================
            
            
            % ---------------------------------------------------------
            % TWO GROUPS
            %
            % Direction stores signed Cliff's delta.
            %
            % EffectSize stores abs(Cliff delta), so showing both
            % Direction and EffectSize numerically would be redundant.
            % ---------------------------------------------------------
            
            if nGroups == 2
                
                Tshow = ...
                    table( ...
                    app.resultTop.Rank, ...
                    string(app.resultTop.RatioName), ...
                    app.resultTop.PValue, ...
                    app.resultTop.QValue, ...
                    app.resultTop.Direction, ...
                    effectLevel, ...
                    significance, ...
                    'VariableNames', { ...
                    'Rank', ...
                    'Ratio', ...
                    'p_value', ...
                    'q_value', ...
                    'Cliff_delta', ...
                    'Effect_level', ...
                    'Significance'});
                
                
                % ---------------------------------------------------------
                % THREE OR MORE GROUPS
                %
                % Kruskal-Wallis has no signed direction.
                % EffectSize stores epsilon squared.
                % ---------------------------------------------------------
                
            else
                
                Tshow = ...
                    table( ...
                    app.resultTop.Rank, ...
                    string(app.resultTop.RatioName), ...
                    app.resultTop.PValue, ...
                    app.resultTop.QValue, ...
                    app.resultTop.EffectSize, ...
                    effectLevel, ...
                    significance, ...
                    'VariableNames', { ...
                    'Rank', ...
                    'Ratio', ...
                    'p_value', ...
                    'q_value', ...
                    'epsilon_sq', ...
                    'Effect_level', ...
                    'Significance'});
                
            end
            
            
            % =========================================================
            % 12. FORMAT NUMERIC DISPLAY
            % =========================================================
            
            Tshow.p_value = ...
                string( ...
                compose( ...
                '%.3e', ...
                Tshow.p_value));
            
            
            Tshow.q_value = ...
                string( ...
                compose( ...
                '%.3e', ...
                Tshow.q_value));
            
            
            if ismember( ...
                    'Cliff_delta', ...
                    Tshow.Properties.VariableNames)
                
                Tshow.Cliff_delta = ...
                    round( ...
                    Tshow.Cliff_delta, ...
                    3);
                
            end
            
            
            if ismember( ...
                    'epsilon_sq', ...
                    Tshow.Properties.VariableNames)
                
                Tshow.epsilon_sq = ...
                    round( ...
                    Tshow.epsilon_sq, ...
                    3);
                
            end
            
            
            % =========================================================
            % 13. SEND DATA TO EXISTING UITABLE
            % =========================================================
            
            app.UITable2.Data = ...
                Tshow;
            % =========================================================
            % COLOR SIGNIFICANCE COLUMN
            % =========================================================
            
            try
                
                removeStyle(app.UITable2);
                
            catch
            end
            
            
            sigCol = ...
                width(Tshow);
            
            
            for r = 1:height(Tshow)
                
                sigText = ...
                    string(Tshow.Significance(r));
                
                
                if sigText == "✓✓✓"
                    
                    s = uistyle( ...
                        'FontColor', [0.00 0.50 0.15], ...
                        'FontWeight', 'bold');
                    
                    addStyle( ...
                        app.UITable2, ...
                        s, ...
                        'cell', ...
                        [r sigCol]);
                    
                    
                elseif sigText == "✓✓"
                    
                    s = uistyle( ...
                        'FontColor', [0.10 0.55 0.20], ...
                        'FontWeight', 'bold');
                    
                    addStyle( ...
                        app.UITable2, ...
                        s, ...
                        'cell', ...
                        [r sigCol]);
                    
                    
                elseif sigText == "✓"
                    
                    s = uistyle( ...
                        'FontColor', [0.20 0.60 0.25], ...
                        'FontWeight', 'bold');
                    
                    addStyle( ...
                        app.UITable2, ...
                        s, ...
                        'cell', ...
                        [r sigCol]);
                    
                    
                else
                    
                    s = uistyle( ...
                        'FontColor', [0.45 0.45 0.45]);
                    
                    addStyle( ...
                        app.UITable2, ...
                        s, ...
                        'cell', ...
                        [r sigCol]);
                    
                end
                
            end
            
            
            app.UITable2.RowName = {};
            
            
            % =========================================================
            % 14. FRIENDLY COLUMN NAMES
            % =========================================================
            
            if nGroups == 2
                
                app.UITable2.ColumnName = { ...
                    'Rank', ...
                    'Ratio', ...
                    'p-value', ...
                    'q-value', ...
                    'Cliff delta', ...
                    'Effect level', ...
                    'Significance level'};
                
            else
                
                app.UITable2.ColumnName = { ...
                    'Rank', ...
                    'Ratio', ...
                    'p-value', ...
                    'q-value', ...
                    'epsilon squared', ...
                    'Effect level', ...
                    'Sig.'};
                
            end
            
            
            % =========================================================
            % 15. TABLE WIDTHS
            % =========================================================
            
            app.UITable2.ColumnWidth = { ...
                55, ...
                'auto', ...
                'auto', ...
                'auto', ...
                'auto', ...
                'auto', ...
                'auto'};


            % =========================================================
            % 16. CLEAR RESULT AXES
            % =========================================================
            
            cla( ...
                app.UIAxes, ...
                'reset');
            
            
            title( ...
                app.UIAxes, ...
                'Select row(s) in table to show boxplot');
            
            
            box( ...
                app.UIAxes, ...
                'on');
            
            
            grid( ...
                app.UIAxes, ...
                'off');
            
            
            cla( ...
                app.UIAxes2, ...
                'reset');
            
            
            axis( ...
                app.UIAxes2, ...
                'off');
            
            
            title( ...
                app.UIAxes2, ...
                'Legend');
            
            
            % =========================================================
            % 17. RESET CURRENT TABLE SELECTION STATE
            % =========================================================
            
            app.lastSelectedRows = [];
            
            
            % =========================================================
            % 18. SEND FULL RESULT TO PARENT APP
            %
            % IMPORTANT:
            % Parent receives app.resultAll, not the compact Tshow.
            %
            % Therefore no metadata is lost by simplifying UITable2.
            % =========================================================
            
            if ~isempty(app.mainApp) && ...
                    isvalid(app.mainApp)
                
                app.mainApp.receiveRatioScreeningResults( ...
                    app.selectedPeakTable, ...
                    app.resultAll, ...
                    app.resultTop, ...
                    app.ratioValueMatrix, ...
                    app.groupLabels, ...
                    app.groupNames, ...
                    app.spectrumNames);
                
            end
            
        end


        function level = classifyRatioEffect(app, effectValue, nGroups)
            
            %#ok<INUSD>
            
            level = "Unknown";
            
            
            if ~isfinite(effectValue)
                return
            end
            
            
            effectValue = ...
                abs(double(effectValue));
            
            
            % =========================================================
            % TWO GROUPS:
            % Cliff's delta magnitude
            % =========================================================
            
            if nGroups == 2
                
                if effectValue < 0.147
                    
                    level = "Negligible";
                    
                elseif effectValue < 0.33
                    
                    level = "Small";
                    
                elseif effectValue < 0.474
                    
                    level = "Medium";
                    
                else
                    
                    level = "Large";
                    
                end
                
                
                % =========================================================
                % THREE OR MORE GROUPS:
                % epsilon squared
                % =========================================================
                
            else
                
                if effectValue < 0.01
                    
                    level = "Negligible";
                    
                elseif effectValue < 0.06
                    
                    level = "Small";
                    
                elseif effectValue < 0.14
                    
                    level = "Medium";
                    
                else
                    
                    level = "Large";
                    
                end
                
            end
            
        end
        
        
        function Tout = normalizePeakLibraryForRatio( ...
                app, ...
                Tin, ...
                projectName)
            
            Tout = table();
            
            
            % =========================================================
            % Empty input
            % =========================================================
            if isempty(Tin) || ~istable(Tin)
                return
            end
            
            
            % =========================================================
            % Already canonical Ratio format
            % =========================================================
            vars = string(Tin.Properties.VariableNames);
            
            requiredVars = [ ...
                "LowWN", ...
                "HighWN", ...
                "CenterWN", ...
                "Interpretation"];
            
            if all(ismember(requiredVars, vars))
                
                Tout = Tin;
                
                if ~ismember("ExperimentalPeak", vars)
                    
                    Tout.ExperimentalPeak = ...
                        compose("%.1f", Tout.CenterWN);
                    
                    Tout = movevars( ...
                        Tout, ...
                        "ExperimentalPeak", ...
                        "Before", ...
                        1);
                end
                
                return
            end
            
            
            % =========================================================
            % Identify source columns
            % =========================================================
            varsLower = lower(strtrim(vars));
            
            
            theoryCol = find( ...
                contains(varsLower, "theoretical"), ...
                1, ...
                'first');
            
            
            interpretationCol = find( ...
                contains(varsLower, "interpretation") | ...
                contains(varsLower, "meaning"), ...
                1, ...
                'first');
            
            
            if isempty(theoryCol) || isempty(interpretationCol)
                
                uialert( ...
                    app.UIFigure, ...
                    ['Peak library format is not recognized.' newline newline ...
                    'The library must contain theoretical peak and ' ...
                    'interpretation columns.'], ...
                    'Peak library error');
                
                return
            end
            
            
            % =========================================================
            % Find current project observed-peak column
            % =========================================================
            projectCol = [];
            
            if strlength(strtrim(projectName)) > 0
                
                projectCol = find( ...
                    strcmpi( ...
                    strtrim(vars), ...
                    strtrim(projectName)), ...
                    1, ...
                    'first');
                
            end
            
            
            % =========================================================
            % Parse rows
            % =========================================================
            n = height(Tin);
            
            ExperimentalPeak = strings(n,1);
            
            LowWN = nan(n,1);
            HighWN = nan(n,1);
            CenterWN = nan(n,1);
            
            Interpretation = strings(n,1);
            
            for k = 1:n
                
                try
                    
                    Interpretation(k) = string( ...
                        Tin{k, interpretationCol});
                    
                catch
                    
                    Interpretation(k) = "";
                    
                end
                
            end
            
            Source = repmat("Defined library", n, 1);
            
            
            theoryText = ...
                string(Tin{:, theoryCol});
            
            
            for k = 1:n
                
                txt = strtrim(theoryText(k));
                
                nums = regexp( ...
                    char(txt), ...
                    '[-+]?\d*\.?\d+', ...
                    'match');
                
                
                if isempty(nums)
                    continue
                end
                
                
                nums = str2double(nums);
                
                
                % -----------------------------------------------------
                % Theoretical center/range
                % -----------------------------------------------------
                if numel(nums) == 1
                    
                    theoryCenter = nums(1);
                    theoryLow = theoryCenter - 5;
                    theoryHigh = theoryCenter + 5;
                    
                else
                    
                    theoryLow = min(nums(1), nums(2));
                    theoryHigh = max(nums(1), nums(2));
                    
                    theoryCenter = mean( ...
                        [theoryLow theoryHigh]);
                    
                end
                
                
                % -----------------------------------------------------
                % Current project observed center
                % -----------------------------------------------------
                observedCenter = NaN;
                
                if ~isempty(projectCol)
                    
                    observedText = ...
                        string(Tin{k, projectCol});
                    
                    obsNums = regexp( ...
                        char(observedText), ...
                        '[-+]?\d*\.?\d+', ...
                        'match');
                    
                    if ~isempty(obsNums)
                        
                        observedCenter = ...
                            str2double(obsNums{1});
                        
                    end
                    
                end
                
                
                % -----------------------------------------------------
                % Use observed center when available
                % -----------------------------------------------------
                if isfinite(observedCenter)
                    
                    center = observedCenter;
                    
                    halfWidth = ...
                        max( ...
                        (theoryHigh - theoryLow) / 2, ...
                        5);
                    
                    low = center - halfWidth;
                    high = center + halfWidth;
                    
                else
                    
                    center = theoryCenter;
                    low = theoryLow;
                    high = theoryHigh;
                    
                end
                
                
                % -----------------------------------------------------
                % Clamp to spectral axis
                % -----------------------------------------------------
                low = max(min(app.wn), low);
                high = min(max(app.wn), high);
                
                
                ExperimentalPeak(k) = txt;
                
                LowWN(k) = low;
                HighWN(k) = high;
                CenterWN(k) = center;
                
            end
            
            
            % =========================================================
            % Keep valid rows only
            % =========================================================
            keep = ...
                isfinite(CenterWN) & ...
                isfinite(LowWN) & ...
                isfinite(HighWN) & ...
                Interpretation ~= "";
            
            
            Tout = table( ...
                ExperimentalPeak(keep), ...
                LowWN(keep), ...
                HighWN(keep), ...
                CenterWN(keep), ...
                Interpretation(keep), ...
                Source(keep), ...
                'VariableNames', { ...
                'ExperimentalPeak', ...
                'LowWN', ...
                'HighWN', ...
                'CenterWN', ...
                'Interpretation', ...
                'Source'});
            
        end


        function label = makePeakDisplayLabel(app, peakRow)
            
            featureType = string(strtrim(app.FeaturesDropDown.Value));
            
            if featureType == ""
                featureType = "Area";
            end
            
            lowWN = double(peakRow.LowWN);
            highWN = double(peakRow.HighWN);
            centerWN = double(peakRow.CenterWN);
            
            interpretation = "";
            
            if ismember( ...
                    'Interpretation', ...
                    peakRow.Properties.VariableNames)
                
                interpretation = ...
                    string(peakRow.Interpretation);
                
            end
            
            if ismissing(interpretation)
                interpretation = "";
            end
            
            switch featureType
                
                case "Exact intensity"
                    
                    label = sprintf( ...
                        'I%.1f', ...
                        centerWN);
                    
                otherwise
                    
                    if abs(highWN - lowWN) < 0.05
                        
                        label = sprintf( ...
                            'A%.1f', ...
                            centerWN);
                        
                    else
                        
                        label = sprintf( ...
                            'A%.1f-%.1f', ...
                            lowWN, ...
                            highWN);
                        
                    end
                    
            end
            
            if strlength(strtrim(interpretation)) > 0
                
                label = string(label) + ...
                    " [" + interpretation + "]";
                
            else
                
                label = string(label);
                
            end
            
        end


        function resultTable = attachRatioWavenumberMetadata( ...
                app, ...
                resultTable)
            
            if isempty(resultTable) || ...
                    ~istable(resultTable)
                
                return
            end
            
            nRows = height(resultTable);
            
            RatioDisplay = ...
                string(resultTable.RatioName);
            
            NumeratorLabel = ...
                strings(nRows,1);
            
            DenominatorLabel = ...
                strings(nRows,1);
            
            NumeratorLowWN = ...
                nan(nRows,1);
            
            NumeratorHighWN = ...
                nan(nRows,1);
            
            NumeratorCenterWN = ...
                nan(nRows,1);
            
            DenominatorLowWN = ...
                nan(nRows,1);
            
            DenominatorHighWN = ...
                nan(nRows,1);
            
            DenominatorCenterWN = ...
                nan(nRows,1);
            
            for r = 1:nRows
                
                if ~ismember( ...
                        'NumPeak', ...
                        resultTable.Properties.VariableNames) || ...
                        ~ismember( ...
                        'DenPeak', ...
                        resultTable.Properties.VariableNames)
                    
                    continue
                end
                
                numPeakID = ...
                    resultTable.NumPeak(r);
                
                denPeakID = ...
                    resultTable.DenPeak(r);
                
                if ~isfinite(numPeakID) || ...
                        ~isfinite(denPeakID)
                    
                    % Custom formula, not a simple numerator/denominator ratio.
                    RatioDisplay(r) = ...
                        string(resultTable.RatioName(r));
                    
                    continue
                end
                
                if isempty(app.selectedPeakTable) || ...
                        ~istable(app.selectedPeakTable) || ...
                        ~ismember( ...
                        'PeakID', ...
                        app.selectedPeakTable.Properties.VariableNames)
                    
                    continue
                end
                
                numRow = find( ...
                    app.selectedPeakTable.PeakID == numPeakID, ...
                    1, ...
                    'first');
                
                denRow = find( ...
                    app.selectedPeakTable.PeakID == denPeakID, ...
                    1, ...
                    'first');
                
                if isempty(numRow) || isempty(denRow)
                    continue
                end
                
                numeratorPeak = ...
                    app.selectedPeakTable(numRow,:);
                
                denominatorPeak = ...
                    app.selectedPeakTable(denRow,:);
                
                NumeratorLowWN(r) = ...
                    double(numeratorPeak.LowWN);
                
                NumeratorHighWN(r) = ...
                    double(numeratorPeak.HighWN);
                
                NumeratorCenterWN(r) = ...
                    double(numeratorPeak.CenterWN);
                
                DenominatorLowWN(r) = ...
                    double(denominatorPeak.LowWN);
                
                DenominatorHighWN(r) = ...
                    double(denominatorPeak.HighWN);
                
                DenominatorCenterWN(r) = ...
                    double(denominatorPeak.CenterWN);
                
                NumeratorLabel(r) = ...
                    app.makePeakDisplayLabel( ...
                    numeratorPeak);
                
                DenominatorLabel(r) = ...
                    app.makePeakDisplayLabel( ...
                    denominatorPeak);
                
                RatioDisplay(r) = ...
                    NumeratorLabel(r) + ...
                    " / " + ...
                    DenominatorLabel(r);
                
            end
            
            resultTable.RatioDisplay = ...
                RatioDisplay;
            
            resultTable.NumeratorLabel = ...
                NumeratorLabel;
            
            resultTable.DenominatorLabel = ...
                DenominatorLabel;
            
            resultTable.NumeratorLowWN = ...
                NumeratorLowWN;
            
            resultTable.NumeratorHighWN = ...
                NumeratorHighWN;
            
            resultTable.NumeratorCenterWN = ...
                NumeratorCenterWN;
            
            resultTable.DenominatorLowWN = ...
                DenominatorLowWN;
            
            resultTable.DenominatorHighWN = ...
                DenominatorHighWN;
            
            resultTable.DenominatorCenterWN = ...
                DenominatorCenterWN;
            
            resultTable = movevars( ...
                resultTable, ...
                { ...
                'RatioDisplay', ...
                'NumeratorLabel', ...
                'DenominatorLabel', ...
                'NumeratorCenterWN', ...
                'DenominatorCenterWN'}, ...
                'After', ...
                'RatioName');
            
        end


        function inverseFWHM = computeInverseFWHM(app, wnBand, yBand)
            
            %#ok<INUSD>
            
            inverseFWHM = NaN;
            
            
            % =========================================================
            % Input cleanup
            % =========================================================
            x = ...
                double(wnBand(:));
            
            y = ...
                double(yBand(:));
            
            
            valid = ...
                isfinite(x) & ...
                isfinite(y);
            
            
            x = ...
                x(valid);
            
            y = ...
                y(valid);
            
            
            if numel(x) < 5
                return
            end
            
            
            % =========================================================
            % Ensure ascending WN axis
            % =========================================================
            [x, order] = ...
                sort(x, 'ascend');
            
            y = ...
                y(order);
            
            
            % Remove duplicate X values
            [x, uniqueIdx] = ...
                unique(x, 'stable');
            
            y = ...
                y(uniqueIdx);
            
            
            if numel(x) < 5
                return
            end
            
            
            % =========================================================
            % Local linear baseline
            %
            % Connect first and last point of selected peak window.
            % =========================================================
            dx = ...
                x(end) - x(1);
            
            
            if ~isfinite(dx) || dx <= 0
                return
            end
            
            
            baseline = ...
                y(1) + ...
                (y(end) - y(1)) .* ...
                (x - x(1)) ./ dx;
            
            
            yc = ...
                y - baseline;
            
            
            % =========================================================
            % Peak maximum
            % =========================================================
            [peakHeight, peakIdx] = ...
                max(yc);
            
            
            if ~isfinite(peakHeight) || peakHeight <= 0
                return
            end
            
            
            % Peak maximum must not be at window boundary
            if peakIdx <= 1 || peakIdx >= numel(x)
                return
            end
            
            
            halfHeight = ...
                0.5 * peakHeight;
            
            
            % =========================================================
            % LEFT HALF-HEIGHT CROSSING
            % =========================================================
            leftY = ...
                yc(1:peakIdx);
            
            
            leftBelowIdx = ...
                find( ...
                leftY <= halfHeight, ...
                1, ...
                'last');
            
            
            if isempty(leftBelowIdx) || ...
                    leftBelowIdx >= peakIdx
                
                return
                
            end
            
            
            i1 = ...
                leftBelowIdx;
            
            i2 = ...
                leftBelowIdx + 1;
            
            
            y1 = ...
                yc(i1);
            
            y2 = ...
                yc(i2);
            
            
            if y2 == y1
                return
            end
            
            
            xLeft = ...
                x(i1) + ...
                (halfHeight - y1) .* ...
                (x(i2) - x(i1)) ./ ...
                (y2 - y1);
            
            
            % =========================================================
            % RIGHT HALF-HEIGHT CROSSING
            % =========================================================
            rightY = ...
                yc(peakIdx:end);
            
            
            rightRelativeIdx = ...
                find( ...
                rightY <= halfHeight, ...
                1, ...
                'first');
            
            
            if isempty(rightRelativeIdx) || ...
                    rightRelativeIdx <= 1
                
                return
                
            end
            
            
            j2 = ...
                peakIdx + rightRelativeIdx - 1;
            
            j1 = ...
                j2 - 1;
            
            
            y1 = ...
                yc(j1);
            
            y2 = ...
                yc(j2);
            
            
            if y2 == y1
                return
            end
            
            
            xRight = ...
                x(j1) + ...
                (halfHeight - y1) .* ...
                (x(j2) - x(j1)) ./ ...
                (y2 - y1);
            
            
            % =========================================================
            % FWHM
            % =========================================================
            fwhm = ...
                xRight - xLeft;
            
            
            if ~isfinite(fwhm) || fwhm <= 0
                return
            end
            
            
            inverseFWHM = ...
                1 / fwhm;
            
        end


        function flag = classifyQValue(app, qValue)
            
            %#ok<INUSD>
            
            if ~isfinite(qValue)
                
                flag = "";
                
            elseif qValue < 0.001
                
                flag = "✓✓✓";
                
            elseif qValue < 0.01
                
                flag = "✓✓";
                
            elseif qValue < 0.05
                
                flag = "✓";
                
            else
                
                flag = "—";
                
            end
            
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            app.runABForAllScreening();
        end

        % Button pushed function: AddpeaksfromspectraButton
        function AddpeaksfromspectraButtonPushed(app, event)
            choice = questdlg( ...
                'How do you want to add peaks?', ...
                'Add peaks', ...
                'Type range/center', ...
                'Click on spectra', ...
                'Cancel', ...
                'Type range/center');
            
            if isempty(choice) || strcmp(choice,'Cancel')
                return
            end
            
            switch choice
                case 'Type range/center'
                    manualPeaks = app.inputManualPeaksByText();
                    
                case 'Click on spectra'
                    manualPeaks = app.selectPeaksOnSeparateFigure();
                    
                otherwise
                    return
            end
            app.appendManualPeaksToTree(manualPeaks);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.resetRatioApp();
        end

        % Value changed function: FeaturesDropDown
        function FeaturesDropDownValueChanged(app, event)
            value = app.FeaturesDropDown.Value;
            if string(value) ~= ""
                app.featureType = string(value);
            end
        end

        % Button pushed function: SaveimagesButton
        function SaveimagesButtonPushed(app, event)
            app.saveCurrentFigure();
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            app.finishRatioApp();
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            
        end

        % Cell selection callback: UITable2
        function UITable2CellSelection(app, event)
            indices = event.Indices;
            if isempty(event.Indices)
                return
            end
            
            selectedRows = unique(event.Indices(:,1));
            app.plotSelectedRatioRows(selectedRows);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)
            txt = strtrim(string(app.EditField.Value));
            
            if txt == ""
                app.boxYLim = [];
                if ~isempty(app.lastSelectedRows)
                    app.plotSelectedRatioRows(app.lastSelectedRows);
                end
                return
            end
            
            parts = regexp(txt, '[,;\s]+', 'split');
            parts = parts(parts ~= "");
            
            if numel(parts) ~= 2
                uialert(app.UIFigure, ...
                    'Enter Y limit as: ymin ymax. Example: 0 2.5', ...
                    'Invalid Y filter');
                return
            end
            
            ymin = str2double(parts{1});
            ymax = str2double(parts{2});
            
            if isnan(ymin) || isnan(ymax) || ymin >= ymax
                uialert(app.UIFigure, ...
                    'Ymin and Ymax must be numeric, and Ymin < Ymax.', ...
                    'Invalid Y filter');
                return
            end
            
            app.boxYLim = [ymin ymax];
            
            if ~isempty(app.lastSelectedRows)
                app.plotSelectedRatioRows(app.lastSelectedRows);
            end
        end

        % Button pushed function: FormulaButton
        function FormulaButtonPushed(app, event)
            app.runFormulaScreening();
        end

        % Value changed function: meanvalueCheckBox
        function meanvalueCheckBoxValueChanged(app, event)
            if ~isempty(app.lastSelectedRows)
                app.plotSelectedRatioRows( ...
                    app.lastSelectedRows);
            end
        end

        % Value changed function: medianvalueCheckBox
        function medianvalueCheckBoxValueChanged(app, event)
            if ~isempty(app.lastSelectedRows)
                app.plotSelectedRatioRows( ...
                    app.lastSelectedRows);
            end
        end

        % Button pushed function: RationoteButton
        function RationoteButtonPushed(app, event)
            % =========================================================
            % 1. REQUIRE A CURRENTLY VIEWED RATIO
            % =========================================================
            
            if isempty(app.lastSelectedRows)
                
                uialert( ...
                    app.UIFigure, ...
                    'Select one ratio row and display its boxplot first.', ...
                    'Ratio note');
                
                return
            end
            
            
            rows = ...
                unique(app.lastSelectedRows(:));
            
            
            if numel(rows) ~= 1
                
                uialert( ...
                    app.UIFigure, ...
                    ['Ratio note stores one reusable ratio at a time.' newline ...
                    'Please select exactly one ratio row.'], ...
                    'Ratio note');
                
                return
            end
            
            
            r = ...
                rows(1);
            
            
            if r < 1 || ...
                    r > height(app.resultTop)
                
                return
            end
            
            
            % =========================================================
            % 2. REQUIRE PARENT
            % =========================================================
            
            if isempty(app.mainApp) || ...
                    ~isvalid(app.mainApp)
                
                uialert( ...
                    app.UIFigure, ...
                    'Parent Single mode app is not available.', ...
                    'Ratio note');
                
                return
            end
            
            
            % =========================================================
            % 3. SEND ONLY THE RECIPE / METADATA
            %
            % Do NOT send or duplicate ratioValueMatrix here.
            % =========================================================
            
            ratioRow = ...
                app.resultTop(r,:);
            
            
            featureType = ...
                string(app.FeaturesDropDown.Value);
            
            
            try
                
                [added, messageText] = ...
                    app.mainApp.receiveRatioNote( ...
                    ratioRow, ...
                    featureType);
                
                
                if added
                    
                    msgbox( ...
                        char(messageText), ...
                        'Ratio note');
                    
                else
                    
                    uialert( ...
                        app.UIFigure, ...
                        char(messageText), ...
                        'Ratio note');
                    
                end
                
                
            catch ME
                
                uialert( ...
                    app.UIFigure, ...
                    sprintf( ...
                    'Cannot save Ratio note:\n%s', ...
                    ME.message), ...
                    'Ratio note error');
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Box plot')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.GridLayout);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.Tree = uitree(app.GridLayout);
            app.Tree.Multiselect = 'on';
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Node = uitreenode(app.Tree);
            app.Node.Text = 'Node';
            app.Node2 = uitreenode(app.Node);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.Node);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.Node);
            app.Node4.Text = 'Node4';
            app.UITable2 = uitable(app.GridLayout);
            app.UITable2.ColumnName = {'Rank'; 'Ratio name'; 'Separation score'; 'p-value'; 'q-value'; 'Effect size'; 'Direction'; 'Test type'};
            app.UITable2.RowName = {};
            app.UITable2.CellSelectionCallback = createCallbackFcn(app, @UITable2CellSelection, true);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.AddpeaksfromspectraButton = uibutton(app.GridLayout2, 'push');
            app.AddpeaksfromspectraButton.ButtonPushedFcn = createCallbackFcn(app, @AddpeaksfromspectraButtonPushed, true);
            app.AddpeaksfromspectraButton.Text = 'Add peaks from spectra';
            app.SaveimagesButton = uibutton(app.GridLayout2, 'push');
            app.SaveimagesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveimagesButtonPushed, true);
            app.SaveimagesButton.Text = 'Save images';
            app.FinishButton = uibutton(app.GridLayout2, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.FilterButton = uibutton(app.GridLayout2, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Text = 'Filter';
            app.EditField = uieditfield(app.GridLayout2, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.ResetButton = uibutton(app.GridLayout2, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.FeaturesDropDownLabel = uilabel(app.GridLayout2);
            app.FeaturesDropDownLabel.Text = 'Features';
            app.FeaturesDropDown = uidropdown(app.GridLayout2);
            app.FeaturesDropDown.Items = {'', 'Area', 'Exact intensity', '1/FWHM'};
            app.FeaturesDropDown.ValueChangedFcn = createCallbackFcn(app, @FeaturesDropDownValueChanged, true);
            app.FeaturesDropDown.Value = '';
            app.FormulaButton = uibutton(app.GridLayout2, 'push');
            app.FormulaButton.ButtonPushedFcn = createCallbackFcn(app, @FormulaButtonPushed, true);
            app.FormulaButton.Text = 'Formula';
            app.ExcelexportButton = uibutton(app.GridLayout2, 'push');
            app.ExcelexportButton.Text = 'Excel export';
            app.ApplyButton = uibutton(app.GridLayout2, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply ';
            app.InterpretsupportButton = uibutton(app.GridLayout2, 'push');
            app.InterpretsupportButton.Text = 'Interpret support';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.meanvalueCheckBox = uicheckbox(app.GridLayout3);
            app.meanvalueCheckBox.ValueChangedFcn = createCallbackFcn(app, @meanvalueCheckBoxValueChanged, true);
            app.meanvalueCheckBox.Text = '   mean value';
            app.medianvalueCheckBox = uicheckbox(app.GridLayout3);
            app.medianvalueCheckBox.ValueChangedFcn = createCallbackFcn(app, @medianvalueCheckBoxValueChanged, true);
            app.medianvalueCheckBox.Text = '   median value';
            app.RationoteButton = uibutton(app.GridLayout3, 'push');
            app.RationoteButton.ButtonPushedFcn = createCallbackFcn(app, @RationoteButtonPushed, true);
            app.RationoteButton.Text = 'Ratio note';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sg_ratio

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end