classdef sg_smooth < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout5                    matlab.ui.container.GridLayout
        SaveimagesButton               matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
        FinishButton                   matlab.ui.control.Button
        Tree                           matlab.ui.container.Tree
        Node                           matlab.ui.container.TreeNode
        Node2                          matlab.ui.container.TreeNode
        Node3                          matlab.ui.container.TreeNode
        Node4                          matlab.ui.container.TreeNode
        SmoothcontrolPanel             matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        WnfilterButton                 matlab.ui.control.Button
        EditField                      matlab.ui.control.EditField
        MapcolorsDropDown              matlab.ui.control.DropDown
        MapcolorsDropDownLabel         matlab.ui.control.Label
        SmoothdataButton               matlab.ui.control.Button
        PolynomialorderEditField       matlab.ui.control.NumericEditField
        PolynomialorderEditFieldLabel  matlab.ui.control.Label
        WindowlengthEditField          matlab.ui.control.NumericEditField
        WindowlengthEditFieldLabel     matlab.ui.control.Label
        Panel_2                        matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        UIAxes6_7                      matlab.ui.control.UIAxes
        UIAxes6_6                      matlab.ui.control.UIAxes
        UIAxes6_5                      matlab.ui.control.UIAxes
        Panel                          matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        UIAxes6_4                      matlab.ui.control.UIAxes
        UIAxes6_3                      matlab.ui.control.UIAxes
        UIAxes6_2                      matlab.ui.control.UIAxes
        UIAxes6                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        mainApp
        mode
        wn
        
        rawSpectra
        rawCube
        
        smoothedSpectra
        smoothedCube
        
        isSmoothed logical = false
        selectedIndex double = []
        
        selectedBandIndex double = []
        selectedBandRange double = []
        
        metricTable table
        worstIndex double = []
        
        spectralDef
        
    end
    
    methods (Access = private)
        
        % Function 1:
        function setupTooltips(app)
            winTip = sprintf([ ...
                'Savitzky-Golay window length.\n\n' ...
                'Rules:\n' ...
                '- Must be an odd number: 5, 7, 9, 11, 15, 21...\n' ...
                '- Must be larger than polynomial order.\n\n' ...
                'Effect:\n' ...
                '- Larger window = stronger smoothing, less noise.\n' ...
                '- Too large = Raman peaks may become broader/flatter.\n' ...
                '- Smaller window = preserves narrow peaks, but leaves more noise.\n\n' ...
                'Examples:\n' ...
                '- Low-noise spectra: window 5 or 7, poly 2\n' ...
                '- General Raman spectra: window 11, poly 3\n' ...
                '- Noisy biological spectra: window 15 or 21, poly 3\n' ...
                '- Very sharp/narrow peaks: window 5 or 7, poly 2']);
            polyTip = sprintf([ ...
                'Savitzky-Golay polynomial order.\n\n' ...
                'Rules:\n' ...
                '- Must be >= 1.\n' ...
                '- Must be smaller than window length.\n' ...
                '- Usually use 2 or 3 for Raman spectra.\n\n' ...
                'Effect:\n' ...
                '- Lower poly = smoother result, but may flatten peak shape.\n' ...
                '- Higher poly = follows peak curvature better, but may keep noise.\n\n' ...
                'Examples:\n' ...
                '- Safe/basic smoothing: window 7, poly 2\n' ...
                '- Balanced default: window 11, poly 3\n' ...
                '- Strong smoothing: window 21, poly 3\n' ...
                '- Avoid poly 4+ unless spectra are very clean and peaks are broad.']);
            app.WindowlengthEditField.Tooltip = winTip;
            app.WindowlengthEditFieldLabel.Tooltip = winTip;
            app.PolynomialorderEditField.Tooltip = polyTip;
            app.PolynomialorderEditFieldLabel.Tooltip = polyTip;
        end
        % Function 2:
        function populateSingleTree(app)
            delete(app.Tree.Children);
            root = uitreenode(app.Tree);
            root.Text = 'Single spectra';
            n = size(app.rawSpectra, 2);
            for k = 1:n
                node = uitreenode(root);
                node.Text = sprintf('Spectrum %03d', k);
                node.NodeData = k;
            end
            expand(root);
        end
        
        % Function 3:
        function populateScanTree(app)
            delete(app.Tree.Children);
            root = uitreenode(app.Tree);
            root.Text = 'Scan Raman';
            node1 = uitreenode(root);
            node1.Text = 'Select single band';
            node1.NodeData = 'single_band';
            node2 = uitreenode(root);
            node2.Text = 'Select band range';
            node2.NodeData = 'band_range';
            expand(root);
        end
        
        % Function 4:
        function ok = validateSGParams(app)
            win = round(app.WindowlengthEditField.Value);
            poly = round(app.PolynomialorderEditField.Value);
            ok = false;
            if win < 3
                uialert(app.UIFigure, ...
                    'Window length must be at least 3.', ...
                    'Invalid parameter');
                return
            end
            if mod(win, 2) == 0
                uialert(app.UIFigure, ...
                    'Window length must be an odd number.', ...
                    'Invalid parameter');
                return
            end
            if poly < 1
                uialert(app.UIFigure, ...
                    'Polynomial order must be at least 1.', ...
                    'Invalid parameter');
                return
            end
            if poly >= win
                uialert(app.UIFigure, ...
                    'Polynomial order must be smaller than window length.', ...
                    'Invalid parameter');
                return
            end
            ok = true;
        end
        
        % Function 5:
        function ySmooth = applySGToMatrix(app, y)
            ySmooth = zeros(size(y));
            for k = 1:size(y,2)
                ySmooth(:,k) = app.smoothOneSpectrum(y(:,k));
            end
        end
        
        % Function 6:
        function smoothCube = applySGToCube(app, cube)
            ny = size(cube,1);
            nx = size(cube,2);
            nWn = size(cube,3);
            nPix = ny * nx;
            matrix = reshape(permute(cube, [3 2 1]), nWn, nPix);
            smoothMatrix = zeros(size(matrix));
            d = uiprogressdlg(app.UIFigure, ...
                'Title', 'Smoothing scan data', ...
                'Message', 'Preparing Savitzky-Golay smoothing...', ...
                'Indeterminate', 'off', ...
                'Cancelable', 'on');
            cleanupObj = onCleanup(@() delete(d));
            blockSize = 200;   % tăng lên 500 hoặc 1000 nếu máy mạnh
            for startIdx = 1:blockSize:nPix
                if d.CancelRequested
                    error('UserCancelled:Smoothing', ...
                        'Smoothing was cancelled by user.');
                end
                endIdx = min(startIdx + blockSize - 1, nPix);
                for k = startIdx:endIdx
                    smoothMatrix(:,k) = app.smoothOneSpectrum(matrix(:,k));
                end
                d.Value = endIdx / nPix;
                d.Message = sprintf( ...
                    'Smoothing pixel %d / %d...', ...
                    endIdx, nPix);
                drawnow limitrate
            end
            smoothCube = permute(reshape(smoothMatrix, nWn, nx, ny), [3 2 1]);
        end
        
        
        % Function 7:
        function plotSingleSpectra(app, selectedIdx)
            cla(app.UIAxes);
            cla(app.UIAxes2);
            raw = app.rawSpectra;
            smoothed = app.smoothedSpectra;
            wn = app.wn;
            rawMean = mean(raw, 2, 'omitnan');
            rawSD = std(raw, 0, 2, 'omitnan');
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, wn, raw, 'Color', [0.75 0.75 0.75], 'LineWidth', 0.5);
            plot(app.UIAxes, wn, rawMean, 'k', 'LineWidth', 1.8);
            plot(app.UIAxes, wn, rawMean + rawSD, '--k', 'LineWidth', 0.8);
            plot(app.UIAxes, wn, rawMean - rawSD, '--k', 'LineWidth', 0.8);
            if ~isempty(selectedIdx)
                plot(app.UIAxes, wn, raw(:,selectedIdx), 'r', 'LineWidth', 2.2);
            end
            hold(app.UIAxes, 'off');
            title(app.UIAxes, 'Raw spectra');
            xlabel(app.UIAxes, 'Wavenumber (cm^{-1})');
            ylabel(app.UIAxes, 'Intensity');
            xlim(app.UIAxes, [min(wn), max(wn)]);
            if ~app.isSmoothed || isempty(smoothed)
                title(app.UIAxes2, 'Axis 2 - smoothed spectra will appear after smoothing');
                return
            end
            smMean = mean(smoothed, 2, 'omitnan');
            smSD = std(smoothed, 0, 2, 'omitnan');
            hold(app.UIAxes2, 'on');
            plot(app.UIAxes2, wn, smoothed, 'Color', [0.75 0.75 0.75], 'LineWidth', 0.5);
            plot(app.UIAxes2, wn, smMean, 'k', 'LineWidth', 1.8);
            plot(app.UIAxes2, wn, smMean + smSD, '--k', 'LineWidth', 0.8);
            plot(app.UIAxes2, wn, smMean - smSD, '--k', 'LineWidth', 0.8);
            if ~isempty(selectedIdx)
                plot(app.UIAxes2, wn, smoothed(:,selectedIdx), 'r', 'LineWidth', 2.2);
            end
            hold(app.UIAxes2, 'off');
            title(app.UIAxes2, 'Smoothed spectra');
            xlabel(app.UIAxes2, 'Wavenumber (cm^{-1})');
            ylabel(app.UIAxes2, 'Intensity');
            xlim(app.UIAxes2, [min(wn), max(wn)]);
        end
        
        % Function 8:
        function askAndPlotScanBand(app)
            choice = questdlg( ...
                'Select scan display mode:', ...
                'Scan band display', ...
                'Single wavenumber', ...
                'Wavenumber range', ...
                'Cancel', ...
                'Single wavenumber');
            if isempty(choice) || strcmp(choice, 'Cancel')
                return
            end
            switch choice
                case 'Single wavenumber'
                    answer = inputdlg( ...
                        {'Enter wavenumber:'}, ...
                        'Single wavenumber', ...
                        [1 45], ...
                        {num2str(app.wn(round(end/2)))});
                    if isempty(answer)
                        return
                    end
                    target = str2double(answer{1});
                    if isnan(target)
                        uialert(app.UIFigure, ...
                            'Invalid wavenumber.', ...
                            'Input error');
                        return
                    end
                    [~, idx] = min(abs(app.wn - target));
                    app.selectedBandIndex = idx;
                    app.selectedBandRange = [];
                case 'Wavenumber range'
                    answer = inputdlg( ...
                        {'Start wavenumber:', 'End wavenumber:'}, ...
                        'Wavenumber range', ...
                        [1 45], ...
                        {num2str(min(app.wn)), num2str(max(app.wn))});
                    if isempty(answer)
                        return
                    end
                    wn1 = str2double(answer{1});
                    wn2 = str2double(answer{2});
                    if isnan(wn1) || isnan(wn2)
                        uialert(app.UIFigure, ...
                            'Invalid wavenumber range.', ...
                            'Input error');
                        return
                    end
                    app.selectedBandRange = [min(wn1,wn2), max(wn1,wn2)];
                    app.selectedBandIndex = [];
            end
            app.plotScanBand();
        end
        
        % Function 9:
        function plotScanBand(app)
            if isempty(app.rawCube)
                return
            end
            rawMap = app.extractMap(app.rawCube);
            cla(app.UIAxes);
            imagesc(app.UIAxes, rawMap);
            axis(app.UIAxes, 'image');
            colorbar(app.UIAxes);
            title(app.UIAxes, 'Raw scan map');
            xlabel(app.UIAxes, 'X pixel');
            ylabel(app.UIAxes, 'Y pixel');
            cla(app.UIAxes2);
            if app.isSmoothed && ~isempty(app.smoothedCube)
                smMap = app.extractMap(app.smoothedCube);
                imagesc(app.UIAxes2, smMap);
                axis(app.UIAxes2, 'image');
                colorbar(app.UIAxes2);
                title(app.UIAxes2, 'Smoothed scan map');
                xlabel(app.UIAxes2, 'X pixel');
                ylabel(app.UIAxes2, 'Y pixel');
            else
                title(app.UIAxes2, 'Axis 2 - smoothed scan map will appear after smoothing');
                xlabel(app.UIAxes2, '');
                ylabel(app.UIAxes2, '');
            end
            app.applyMapColor();
        end
        
        % Function 10:
        function map = extractMap(app, cube)
            if ~isempty(app.selectedBandIndex)
                map = cube(:,:,app.selectedBandIndex);
            elseif ~isempty(app.selectedBandRange)
                idx = app.wn >= app.selectedBandRange(1) & ...
                    app.wn <= app.selectedBandRange(2);
                map = mean(cube(:,:,idx), 3, 'omitnan');
            else
                midIdx = round(numel(app.wn)/2);
                map = cube(:,:,midIdx);
                app.selectedBandIndex = midIdx;
            end
        end
        
        % Function 11:
        function applyMapColor(app)
            if app.mode ~= 2
                return
            end
            value = lower(app.MapcolorsDropDown.Value);
            switch value
                case 'jet'
                    colormap(app.UIAxes, jet);
                    colormap(app.UIAxes2, jet);
                case 'hot'
                    colormap(app.UIAxes, hot);
                    colormap(app.UIAxes2, hot);
                case 'turbo'
                    colormap(app.UIAxes, turbo);
                    colormap(app.UIAxes2, turbo);
                case 'parula'
                    colormap(app.UIAxes, parula);
                    colormap(app.UIAxes2, parula);
                case 'bone'
                    colormap(app.UIAxes, bone);
                    colormap(app.UIAxes2, bone);
                case 'gray'
                    colormap(app.UIAxes, gray);
                    colormap(app.UIAxes2, gray);
            end
        end
        
        % Function 12:
        function calculateSingleMetrics(app)
            raw = app.rawSpectra;
            sm = app.smoothedSpectra;
            wn = app.wn;
            n = size(raw,2);
            snrGain = nan(n,1);
            peakShift = nan(n,1);
            fwhmChange = nan(n,1);
            heightRetention = nan(n,1);
            areaRetention = nan(n,1);
            ratioChange = nan(n,1);
            residualRMS = nan(n,1);
            for k = 1:n
                y0 = raw(:,k);
                y1 = sm(:,k);
                residual = y0 - y1;
                residualRMS(k) = sqrt(mean(residual.^2, 'omitnan'));
                [h0, idx0] = max(y0);
                [h1, idx1] = max(y1);
                peakShift(k) = abs(wn(idx1) - wn(idx0));
                base0 = median(y0, 'omitnan');
                base1 = median(y1, 'omitnan');
                heightRetention(k) = ((h1 - base1) / max(eps, (h0 - base0))) * 100;
                noiseRegion = max(1, numel(wn)-round(numel(wn)*0.1)):numel(wn);
                noise0 = std(y0(noiseRegion), 0, 'omitnan');
                noise1 = std(y1(noiseRegion), 0, 'omitnan');
                snr0 = (h0 - base0) / max(eps, noise0);
                snr1 = (h1 - base1) / max(eps, noise1);
                snrGain(k) = snr1 / max(eps, snr0);
                fwhm0 = app.estimateFWHM(wn, y0, idx0);
                fwhm1 = app.estimateFWHM(wn, y1, idx1);
                fwhmChange(k) = ((fwhm1 - fwhm0) / max(eps, fwhm0)) * 100;
                area0 = trapz(wn, y0 - base0);
                area1 = trapz(wn, y1 - base1);
                areaRetention(k) = (area1 / max(eps, area0)) * 100;
                [~, locs0] = findpeaks(y0, 'SortStr', 'descend', 'NPeaks', 2);
                [~, locs1] = findpeaks(y1, 'SortStr', 'descend', 'NPeaks', 2);
                if numel(locs0) >= 2 && numel(locs1) >= 2
                    r0 = y0(locs0(1)) / max(eps, y0(locs0(2)));
                    r1 = y1(locs1(1)) / max(eps, y1(locs1(2)));
                    ratioChange(k) = ((r1 - r0) / max(eps, r0)) * 100;
                end
            end
            app.metricTable = table( ...
                (1:n)', snrGain, peakShift, fwhmChange, ...
                heightRetention, areaRetention, ratioChange, residualRMS, ...
                'VariableNames', { ...
                'Index', 'SNRGain', 'PeakShift', 'FWHMChange', ...
                'HeightRetention', 'AreaRetention', 'RatioChange', 'ResidualRMS'});
            app.plotMetricSummary();
        end
        
        % Function 13:
        function fwhm = estimateFWHM(app, wn, y, peakIdx)
            baseline = median(y, 'omitnan');
            peakHeight = y(peakIdx) - baseline;
            halfLevel = baseline + peakHeight/2;
            leftIdx = find(y(1:peakIdx) <= halfLevel, 1, 'last');
            rightRel = find(y(peakIdx:end) <= halfLevel, 1, 'first');
            if isempty(leftIdx) || isempty(rightRel)
                fwhm = NaN;
                return
            end
            rightIdx = peakIdx + rightRel - 1;
            fwhm = abs(wn(rightIdx) - wn(leftIdx));
        end
        
        % Function 14:
        function plotMetricSummary(app)
            if isempty(app.metricTable)
                return
            end
            t = app.metricTable;
            axesList = { ...
                app.UIAxes6_4, app.UIAxes6_3, app.UIAxes6_2, app.UIAxes6, ...
                app.UIAxes6_5, app.UIAxes6_6, app.UIAxes6_7};
            metricNames = { ...
                'SNRGain', 'FWHMChange', 'AreaRetention', 'PeakShift', ...
                'HeightRetention', 'RatioChange', 'ResidualRMS'};
            titles = { ...
                'SNR gain', 'FWHM change (%)', 'Area retention (%)', ...
                'Peak shift', 'Height retention (%)', ...
                'Ratio change (%)', 'Residual RMS'};
            for i = 1:numel(axesList)
                ax = axesList{i};
                cla(ax);
                data = t.(metricNames{i});
                data = data(~isnan(data));
                if isempty(data)
                    title(ax, titles{i});
                    continue
                end
                histogram(ax, data);
                title(ax, titles{i});
                ax.Box = 'on';
            end
        end
        
        % Function 15:
        function updateWorstSpectra(app)
            if isempty(app.metricTable)
                app.calculateSingleMetrics();
            end
            t = app.metricTable;
            z = @(x) (x - median(x, 'omitnan')) ./ max(eps, iqr(x));
            badScore = ...
                z(abs(t.PeakShift)) + ...
                z(abs(t.FWHMChange)) + ...
                z(abs(100 - t.AreaRetention)) + ...
                z(abs(100 - t.HeightRetention)) + ...
                z(abs(t.RatioChange)) + ...
                z(t.ResidualRMS) - ...
                z(t.SNRGain);
            badScore(isnan(badScore)) = 0;
            [~, order] = sort(badScore, 'descend');
            nWorst = min(10, numel(order));
            app.worstIndex = order(1:nWorst);
            delete(app.Tree.Children);
            root = uitreenode(app.Tree);
            root.Text = 'Worst spectra';
            for i = 1:nWorst
                idx = app.worstIndex(i);
                node = uitreenode(root);
                node.Text = sprintf('Worst %02d: Spectrum %03d', i, idx);
                node.NodeData = idx;
            end
            expand(root);
            if ~isempty(app.worstIndex)
                app.selectedIndex = app.worstIndex(1);
                app.plotSingleSpectra(app.selectedIndex);
            end
        end
        
        
        % Function 16:
        function clearOutputDisplays(app)
            cla(app.UIAxes2);
            title(app.UIAxes2, 'Axis 2 - smoothed result will appear after smoothing');
            xlabel(app.UIAxes2, '');
            ylabel(app.UIAxes2, '');
            colorbar(app.UIAxes2, 'off');
            metricAxes = { ...
                app.UIAxes6_4, app.UIAxes6_3, app.UIAxes6_2, app.UIAxes6, ...
                app.UIAxes6_5, app.UIAxes6_6, app.UIAxes6_7};
            metricTitles = { ...
                'SNR gain', 'FWHM change (%)', 'Area retention (%)', ...
                'Peak shift', 'Height retention (%)', ...
                'Ratio change (%)', 'Residual RMS'};
            for i = 1:numel(metricAxes)
                ax = metricAxes{i};
                cla(ax);
                title(ax, metricTitles{i});
                ax.XTick = [];
                ax.YTick = [];
                ax.Box = 'on';
            end
        end
        
        
        % Function 17:
        function plotSingleRawOnly(app, selectedIdx)
            cla(app.UIAxes);
            raw = app.rawSpectra;
            wn = app.wn;
            rawMean = mean(raw, 2, 'omitnan');
            rawSD = std(raw, 0, 2, 'omitnan');
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, wn, raw, 'Color', [0.75 0.75 0.75], 'LineWidth', 0.5);
            plot(app.UIAxes, wn, rawMean, 'k', 'LineWidth', 1.8);
            plot(app.UIAxes, wn, rawMean + rawSD, '--k', 'LineWidth', 0.8);
            plot(app.UIAxes, wn, rawMean - rawSD, '--k', 'LineWidth', 0.8);
            if ~isempty(selectedIdx)
                plot(app.UIAxes, wn, raw(:,selectedIdx), 'r', 'LineWidth', 2.2);
            end
            hold(app.UIAxes, 'off');
            title(app.UIAxes, 'Raw spectra');
            xlabel(app.UIAxes, 'Wavenumber (cm^{-1})');
            ylabel(app.UIAxes, 'Intensity');
            xlim(app.UIAxes, [min(wn), max(wn)]);
        end
        
        % Function 18:
        function plotScanRawOnly(app)
            if isempty(app.rawCube)
                return
            end
            rawMap = app.extractMap(app.rawCube);
            cla(app.UIAxes);
            imagesc(app.UIAxes, rawMap);
            axis(app.UIAxes, 'image');
            colorbar(app.UIAxes);
            title(app.UIAxes, 'Raw scan map');
            xlabel(app.UIAxes, 'X pixel');
            ylabel(app.UIAxes, 'Y pixel');
            app.applyMapColor();
        end
        
        
        % Function 19:
        function calculateScanMetrics(app)
            raw = app.rawCube;
            sm = app.smoothedCube;
            if isempty(raw) || isempty(sm)
                return
            end
            rawMatrix = reshape(permute(raw, [3 2 1]), numel(app.wn), []);
            smMatrix  = reshape(permute(sm,  [3 2 1]), numel(app.wn), []);
            oldRawSpectra = app.rawSpectra;
            oldSmSpectra = app.smoothedSpectra;
            app.rawSpectra = rawMatrix;
            app.smoothedSpectra = smMatrix;
            app.calculateSingleMetrics();
            app.rawSpectra = oldRawSpectra;
            app.smoothedSpectra = oldSmSpectra;
        end
        
        function yOut = smoothOneSpectrum(app, y)
            y = y(:);
            
            win = round(app.WindowlengthEditField.Value);
            poly = round(app.PolynomialorderEditField.Value);
            
            if win < 3
                win = 3;
            end
            
            if mod(win, 2) == 0
                win = win + 1;
            end
            
            if poly < 1
                poly = 1;
            end
            
            if poly >= win
                poly = win - 2;
            end
            
            mask = true(size(app.wn(:)));
            
            if ~isempty(app.spectralDef) && isfield(app.spectralDef, 'roiMask') && ...
                    numel(app.spectralDef.roiMask) == numel(app.wn)
                mask = app.spectralDef.roiMask(:);
            end
            
            if ~isempty(app.spectralDef) && isfield(app.spectralDef, 'excludeMask') && ...
                    numel(app.spectralDef.excludeMask) == numel(app.wn)
                mask = mask & ~app.spectralDef.excludeMask(:);
            end
            
            yOut = y;
            
            segments = app.maskToSegments(mask);
            
            for s = 1:size(segments,1)
                idx = segments(s,1):segments(s,2);
                
                if numel(idx) < win
                    continue
                end
                
                yOut(idx) = sgolayfilt(y(idx), poly, win);
            end
        end
        
        
        function segments = maskToSegments(app, mask)
            mask = mask(:);
            d = diff([false; mask; false]);
            startIdx = find(d == 1);
            endIdx = find(d == -1) - 1;
            segments = [startIdx, endIdx];
        end
        
    end
    
    methods (Access = public)
        
        % Function 1
        function receiveData(app, mainApp, mode, wn, spectra, cube)
            if isprop(mainApp, 'spectralDef') && ~isempty(mainApp.spectralDef)
                app.spectralDef = mainApp.spectralDef;
            else
                app.spectralDef = [];
            end
            app.mainApp = mainApp;
            app.mode = mode;
            app.wn = wn(:);
            app.rawSpectra = [];
            app.rawCube = [];
            app.smoothedSpectra = [];
            app.smoothedCube = [];
            app.isSmoothed = false;
            app.selectedIndex = [];
            app.selectedBandIndex = [];
            app.selectedBandRange = [];
            app.metricTable = table();
            app.worstIndex = [];
            app.WindowlengthEditField.Value = 11;
            app.PolynomialorderEditField.Value = 3;
            app.setupTooltips();
            app.clearOutputDisplays();
            switch mode
                case 1
                    app.rawSpectra = spectra;
                    app.MapcolorsDropDown.Enable = "off";
                    app.MapcolorsDropDownLabel.Enable = "off";
                    app.populateSingleTree();
                    app.plotSingleRawOnly([]);
                case 2
                    app.rawCube = cube;
                    app.MapcolorsDropDown.Enable = "on";
                    app.MapcolorsDropDownLabel.Enable = "on";
                    app.populateScanTree();
                    app.askAndPlotScanBand();
                    cla(app.UIAxes2);
                    title(app.UIAxes2, 'Axis 2 - smoothed scan map will appear after smoothing');
                otherwise
                    uialert(app.UIFigure, ...
                        'Unknown data mode.', ...
                        'Error');
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: WindowlengthEditField
        function WindowlengthEditFieldValueChanged(app, event)
            value = round(app.WindowlengthEditField.Value);
            if mod(value,2) == 0
                value = value + 1;
            end
            if value < 3
                value = 3;
            end
            app.WindowlengthEditField.Value = value;
        end

        % Value changed function: PolynomialorderEditField
        function PolynomialorderEditFieldValueChanged(app, event)
            value = round(app.PolynomialorderEditField.Value);
            if value < 1
                value = 1;
            end
            app.PolynomialorderEditField.Value = value;
        end

        % Button pushed function: SmoothdataButton
        function SmoothdataButtonPushed(app, event)
            if isempty(app.spectralDef) || ~isfield(app.spectralDef,'roiMask') || ~any(app.spectralDef.roiMask)
                uialert(app.UIFigure,'Please run Data check and define Spectral definition first.','Spectral definition required');
                return
            end
            if ~app.validateSGParams()
                return
            end
            app.SmoothdataButton.Enable = "off";
            app.FinishButton.Enable = "off";
            app.CancelButton.Enable = "on";
            drawnow
            try
                switch app.mode
                    case 1
                        d = uiprogressdlg(app.UIFigure,'Title','Smoothing spectra','Message','Applying Savitzky-Golay smoothing...','Indeterminate','on');
                        cleanupObj = onCleanup(@()delete(d));
                        app.smoothedSpectra = app.applySGToMatrix(app.rawSpectra);
                        app.isSmoothed = true;
                        d.Message = 'Calculating smoothing metrics...';
                        drawnow
                        app.calculateSingleMetrics();
                        app.plotSingleSpectra(app.selectedIndex);
                    case 2
                        app.smoothedCube = app.applySGToCube(app.rawCube);
                        app.isSmoothed = true;
                        d = uiprogressdlg(app.UIFigure,'Title','Calculating scan metrics','Message','Calculating metric histograms for scan pixels...','Indeterminate','on');
                        cleanupMetric = onCleanup(@()delete(d));
                        app.calculateScanMetrics();
                        app.plotScanBand();
                    otherwise
                        uialert(app.UIFigure,'Unknown data mode.','Error');
                end
                uialert(app.UIFigure,'Smoothing completed successfully.','Complete');
            catch ME
                if strcmp(ME.identifier,'UserCancelled:Smoothing')
                    uialert(app.UIFigure,'Smoothing was cancelled. No smoothed data was saved.','Cancelled');
                    app.isSmoothed = false;
                    app.smoothedSpectra = [];
                    app.smoothedCube = [];
                    if app.mode == 1
                        app.plotSingleRawOnly(app.selectedIndex);
                    else
                        app.plotScanRawOnly();
                    end
                else
                    uialert(app.UIFigure,ME.message,'Smoothing error');
                end
            end
            app.SmoothdataButton.Enable = "on";
            app.FinishButton.Enable = "on";
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            if isempty(selectedNodes)
                return
            end
            nodeData = selectedNodes.NodeData;
            switch app.mode
                case 1
                    if isnumeric(nodeData)
                        app.selectedIndex = nodeData;
                        if app.isSmoothed
                            app.plotSingleSpectra(app.selectedIndex);
                        else
                            app.plotSingleRawOnly(app.selectedIndex);
                            app.clearOutputDisplays();
                        end
                    else
                        app.selectedIndex = [];
                        if app.isSmoothed
                            app.plotSingleSpectra([]);
                        else
                            app.plotSingleRawOnly([]);
                            app.clearOutputDisplays();
                        end
                    end
                case 2
                    if ischar(nodeData) || isstring(nodeData)
                        switch string(nodeData)
                            case "single_band"
                                app.selectedBandIndex = [];
                                app.selectedBandRange = [];
                                app.askAndPlotScanBand();
                            case "band_range"
                                app.selectedBandIndex = [];
                                app.selectedBandRange = [];
                                app.askAndPlotScanBand();
                        end
                    end
            end
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            % =========================================================
            % PREVENT REPEATED CLICKS
            % =========================================================
            try
                app.FinishButton.Enable = 'off';
            catch
            end
            
            finishSuccessful = false;
            
            try
                % =====================================================
                % 1. VALIDATE PARENT APP
                % =====================================================
                if isempty(app.mainApp)
                    
                    uialert( ...
                        app.UIFigure, ...
                        ['The parent application is unavailable.' newline ...
                        'Smoothing results cannot be returned.'], ...
                        'Parent app unavailable');
                    
                    return
                end
                
                try
                    parentIsValid = isvalid(app.mainApp);
                catch
                    parentIsValid = false;
                end
                
                if ~parentIsValid
                    
                    uialert( ...
                        app.UIFigure, ...
                        ['The parent application has already been closed.' newline ...
                        'Smoothing results cannot be returned.'], ...
                        'Parent app unavailable');
                    
                    return
                end
                
                if ~ismethod(app.mainApp, 'receiveSmoothData')
                    
                    uialert( ...
                        app.UIFigure, ...
                        ['The parent app does not implement ' ...
                        'receiveSmoothData().'], ...
                        'Connection error');
                    
                    return
                end
                
                % =====================================================
                % 2. VALIDATE MODE AND WAVENUMBER
                % =====================================================
                if isempty(app.mode) || ~ismember(app.mode, [1 2])
                    
                    uialert( ...
                        app.UIFigure, ...
                        'The smoothing data mode is invalid.', ...
                        'Invalid mode');
                    
                    return
                end
                
                if isempty(app.wn)
                    
                    uialert( ...
                        app.UIFigure, ...
                        'The wavenumber axis is empty.', ...
                        'No wavenumber');
                    
                    return
                end
                
                app.wn = double(app.wn(:));
                
                % =====================================================
                % 3. BUILD REPRODUCIBILITY INFORMATION
                % =====================================================
                resultInfo = struct();
                
                resultInfo.SchemaVersion = "1.0";
                resultInfo.Stage = "Smooth";
                resultInfo.Method = "Savitzky-Golay";
                
                resultInfo.Parameters = struct();
                resultInfo.Parameters.WindowLength = ...
                    round(app.WindowlengthEditField.Value);
                
                resultInfo.Parameters.PolynomialOrder = ...
                    round(app.PolynomialorderEditField.Value);
                
                % Retain old direct fields for backward compatibility.
                resultInfo.WindowLength = ...
                    resultInfo.Parameters.WindowLength;
                
                resultInfo.PolynomialOrder = ...
                    resultInfo.Parameters.PolynomialOrder;
                
                resultInfo.Metrics = app.metricTable;
                resultInfo.SpectralDefinition = app.spectralDef;
                
                resultInfo.CompletedAt = string(datetime( ...
                    'now', ...
                    'Format', 'yyyy-MM-dd HH:mm:ss'));
                
                resultInfo.DataChanged = logical(app.isSmoothed);
                
                % =====================================================
                % 4. RESOLVE OUTPUT DATA
                % =====================================================
                outputSpectra = [];
                outputCube = [];
                
                switch app.mode
                    
                    case 1
                        if app.isSmoothed && ...
                                ~isempty(app.smoothedSpectra)
                            
                            outputSpectra = app.smoothedSpectra;
                            resultInfo.ResultStatus = "APPLIED";
                            
                        else
                            outputSpectra = app.rawSpectra;
                            resultInfo.ResultStatus = "UNCHANGED";
                        end
                        
                        if isempty(outputSpectra)
                            
                            uialert( ...
                                app.UIFigure, ...
                                'No spectra are available to return.', ...
                                'Finish error');
                            
                            return
                        end
                        
                        if size(outputSpectra, 1) ~= numel(app.wn)
                            
                            uialert( ...
                                app.UIFigure, ...
                                ['The returned spectra do not match ' ...
                                'the wavenumber axis.'], ...
                                'Dimension error');
                            
                            return
                        end
                        
                    case 2
                        if app.isSmoothed && ...
                                ~isempty(app.smoothedCube)
                            
                            outputCube = app.smoothedCube;
                            resultInfo.ResultStatus = "APPLIED";
                            
                        else
                            outputCube = app.rawCube;
                            resultInfo.ResultStatus = "UNCHANGED";
                        end
                        
                        if isempty(outputCube)
                            
                            uialert( ...
                                app.UIFigure, ...
                                'No scan cube is available to return.', ...
                                'Finish error');
                            
                            return
                        end
                        
                        if size(outputCube, 3) ~= numel(app.wn)
                            
                            uialert( ...
                                app.UIFigure, ...
                                ['The returned cube does not match ' ...
                                'the wavenumber axis.'], ...
                                'Dimension error');
                            
                            return
                        end
                end              
                
                
                if app.mode == 1
                    resultInfo.OutputSize = size(outputSpectra);
                else
                    resultInfo.OutputSize = size(outputCube);
                end
                
                % =====================================================
                % 5. RETURN RESULT TO PARENT
                % =====================================================
                app.mainApp.receiveSmoothData( ...
                    app.mode, ...
                    app.wn, ...
                    outputSpectra, ...
                    outputCube, ...
                    resultInfo);
                
                % =====================================================
                % 6. SAVE PARENT CHECKPOINT
                % =====================================================
                if ismethod(app.mainApp, 'saveCheckpointFromChild')
                    app.mainApp.saveCheckpointFromChild("Smooth");
                end
                
                finishSuccessful = true;
                
            catch ME
                
                reportText = getReport( ...
                    ME, ...
                    'extended', ...
                    'hyperlinks', ...
                    'off');
                
                disp(reportText);
                
                try
                    if isvalid(app.UIFigure)
                        
                        uialert( ...
                            app.UIFigure, ...
                            reportText, ...
                            'Finish smoothing error');
                    end
                catch
                    errordlg(reportText, 'Finish smoothing error');
                end
            end
            
            % =========================================================
            % 7. CLOSE ONLY AFTER SUCCESS
            % =========================================================
            if finishSuccessful
                try
                    if isvalid(app.UIFigure)
                        delete(app.UIFigure);
                    end
                catch
                end
            else
                try
                    if isvalid(app.UIFigure)
                        app.FinishButton.Enable = 'on';
                    end
                catch
                end
            end
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            answer = questdlg( ...
                'Cancel smoothing? No data will be saved.', ...
                'Cancel', ...
                'Yes', 'No', 'No');
            
            if strcmp(answer, 'Yes')
                delete(app.UIFigure);
            end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            
        end

        % Button down function: UIAxes6_4
        function UIAxes6_4ButtonDown(app, event)
            
        end

        % Button down function: UIAxes6_3
        function UIAxes6_3ButtonDown(app, event)
            
        end

        % Button down function: UIAxes6_2
        function UIAxes6_2ButtonDown(app, event)
            
        end

        % Button down function: UIAxes6
        function UIAxes6ButtonDown(app, event)
            
        end

        % Button down function: UIAxes6_5
        function UIAxes6_5ButtonDown(app, event)
            
        end

        % Button down function: UIAxes6_6
        function UIAxes6_6ButtonDown(app, event)
            
        end

        % Button down function: UIAxes6_7
        function UIAxes6_7ButtonDown(app, event)
            
        end

        % Value changed function: MapcolorsDropDown
        function MapcolorsDropDownValueChanged(app, event)
            value = app.MapcolorsDropDown.Value;
            if app.mode ~= 2
                return
            end
            
            app.applyMapColor();
        end

        % Button pushed function: SaveimagesButton
        function SaveimagesButtonPushed(app, event)
            choice = questdlg( ...
                'Select which image to save:', ...
                'Save Smooth images', ...
                'Axis 1', ...
                'Axis 2', ...
                'Both', ...
                'Both');
            
            if isempty(choice)
                return
            end
            
            answer = inputdlg( ...
                {'Enter image resolution DPI:'}, ...
                'Save Smooth image', ...
                [1 45], ...
                {'300'});
            
            if isempty(answer)
                return
            end
            
            dpi = str2double(answer{1});
            
            if isnan(dpi) || dpi <= 0
                uialert(app.UIFigure, ...
                    'DPI must be a positive number.', ...
                    'Invalid DPI');
                return
            end
            
            if ~isempty(app.mainApp) && isvalid(app.mainApp)
                basePath = app.mainApp.askImageFilePath("Smooth", "smooth");
                
                if basePath == ""
                    return
                end
                
                [outFolder, baseName, ~] = fileparts(basePath);
            else
                outFolder = uigetdir('', 'Select folder to save Smooth images');
                
                if isequal(outFolder, 0)
                    return
                end
                
                answerName = inputdlg( ...
                    {'Image file name, without extension:'}, ...
                    'Save Smooth image', ...
                    [1 55], ...
                    {'smooth'});
                
                if isempty(answerName)
                    return
                end
                
                baseName = strtrim(string(answerName{1}));
                
                if baseName == ""
                    baseName = "smooth";
                end
                
                baseName = regexprep(baseName, '[\\/:*?"<>|]', '_');
                timestamp = char(datetime('now', 'Format', 'yyyy-MM-dd_HH-mm-ss'));
                baseName = baseName + "_" + timestamp;
            end
            
            try
                savedFiles = strings(0,1);
                
                switch choice
                    case 'Axis 1'
                        file1 = fullfile(outFolder, char(baseName + "_axis1.png"));
                        exportgraphics(app.UIAxes, file1, 'Resolution', dpi);
                        savedFiles(end+1,1) = string(file1);
                        
                    case 'Axis 2'
                        file2 = fullfile(outFolder, char(baseName + "_axis2.png"));
                        exportgraphics(app.UIAxes2, file2, 'Resolution', dpi);
                        savedFiles(end+1,1) = string(file2);
                        
                    case 'Both'
                        file1 = fullfile(outFolder, char(baseName + "_axis1.png"));
                        file2 = fullfile(outFolder, char(baseName + "_axis2.png"));
                        
                        exportgraphics(app.UIAxes, file1, 'Resolution', dpi);
                        exportgraphics(app.UIAxes2, file2, 'Resolution', dpi);
                        
                        savedFiles(end+1,1) = string(file1);
                        savedFiles(end+1,1) = string(file2);
                end
                
                msgbox(sprintf('Images saved successfully:\n%s', strjoin(savedFiles, newline)), ...
                    'Save images');
                
            catch ME
                uialert(app.UIFigure, ...
                    sprintf('Cannot save images:\n%s', ME.message), ...
                    'Save image error');
            end
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
        end

        % Button pushed function: WnfilterButton
        function WnfilterButtonPushed(app, event)
            txt = strtrim(string(app.EditField.Value));
            
            if txt == ""
                uialert(app.UIFigure, ...
                    'Enter WN range first, for example: 500-1800 or 500 1800.', ...
                    'Empty WN filter');
                return
            end
            
            nums = regexp(char(txt), '[-+]?\d*\.?\d+', 'match');
            nums = str2double(nums);
            
            if numel(nums) < 2 || any(isnan(nums(1:2)))
                uialert(app.UIFigure, ...
                    'Invalid WN range. Use format: 500-1800 or 500 1800.', ...
                    'Invalid WN filter');
                return
            end
            
            lo = min(nums(1), nums(2));
            hi = max(nums(1), nums(2));
            
            if hi < min(app.wn) || lo > max(app.wn)
                uialert(app.UIFigure, ...
                    'Selected range is outside the current wavenumber axis.', ...
                    'Invalid WN filter');
                return
            end
            
            lo = max(lo, min(app.wn));
            hi = min(hi, max(app.wn));
            
            choice = questdlg( ...
                'Apply WN filter to which axis?', ...
                'WN filter', ...
                'Axis 1', ...
                'Axis 2', ...
                'Both', ...
                'Both');
            
            if isempty(choice)
                return
            end
            
            switch choice
                case 'Axis 1'
                    xlim(app.UIAxes, [lo hi]);
                    
                case 'Axis 2'
                    xlim(app.UIAxes2, [lo hi]);
                    
                case 'Both'
                    xlim(app.UIAxes, [lo hi]);
                    xlim(app.UIAxes2, [lo hi]);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Axis 2')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Axis 1')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.UIAxes6 = uiaxes(app.GridLayout2);
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes6.XTick = [];
            app.UIAxes6.YTick = [];
            app.UIAxes6.ButtonDownFcn = createCallbackFcn(app, @UIAxes6ButtonDown, true);
            app.UIAxes6_2 = uiaxes(app.GridLayout2);
            zlabel(app.UIAxes6_2, 'Z')
            app.UIAxes6_2.XTick = [];
            app.UIAxes6_2.YTick = [];
            app.UIAxes6_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes6_2ButtonDown, true);
            app.UIAxes6_3 = uiaxes(app.GridLayout2);
            zlabel(app.UIAxes6_3, 'Z')
            app.UIAxes6_3.XTick = [];
            app.UIAxes6_3.YTick = [];
            app.UIAxes6_3.ButtonDownFcn = createCallbackFcn(app, @UIAxes6_3ButtonDown, true);
            app.UIAxes6_4 = uiaxes(app.GridLayout2);
            zlabel(app.UIAxes6_4, 'Z')
            app.UIAxes6_4.XTick = [];
            app.UIAxes6_4.YTick = [];
            app.UIAxes6_4.ButtonDownFcn = createCallbackFcn(app, @UIAxes6_4ButtonDown, true);
            app.Panel_2 = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.Panel_2);
            app.UIAxes6_5 = uiaxes(app.GridLayout3);
            zlabel(app.UIAxes6_5, 'Z')
            app.UIAxes6_5.XTick = [];
            app.UIAxes6_5.YTick = [];
            app.UIAxes6_5.ButtonDownFcn = createCallbackFcn(app, @UIAxes6_5ButtonDown, true);
            app.UIAxes6_6 = uiaxes(app.GridLayout3);
            zlabel(app.UIAxes6_6, 'Z')
            app.UIAxes6_6.XTick = [];
            app.UIAxes6_6.YTick = [];
            app.UIAxes6_6.ButtonDownFcn = createCallbackFcn(app, @UIAxes6_6ButtonDown, true);
            app.UIAxes6_7 = uiaxes(app.GridLayout3);
            zlabel(app.UIAxes6_7, 'Z')
            app.UIAxes6_7.XTick = [];
            app.UIAxes6_7.YTick = [];
            app.UIAxes6_7.ButtonDownFcn = createCallbackFcn(app, @UIAxes6_7ButtonDown, true);
            app.SmoothcontrolPanel = uipanel(app.GridLayout);
            app.SmoothcontrolPanel.Title = 'Smooth control';
            app.GridLayout4 = uigridlayout(app.SmoothcontrolPanel);
            app.WindowlengthEditFieldLabel = uilabel(app.GridLayout4);
            app.WindowlengthEditFieldLabel.Text = 'Window length';
            app.WindowlengthEditField = uieditfield(app.GridLayout4, 'numeric');
            app.WindowlengthEditField.ValueChangedFcn = createCallbackFcn(app, @WindowlengthEditFieldValueChanged, true);
            app.PolynomialorderEditFieldLabel = uilabel(app.GridLayout4);
            app.PolynomialorderEditFieldLabel.Text = 'Polynomial order';
            app.PolynomialorderEditField = uieditfield(app.GridLayout4, 'numeric');
            app.PolynomialorderEditField.ValueChangedFcn = createCallbackFcn(app, @PolynomialorderEditFieldValueChanged, true);
            app.SmoothdataButton = uibutton(app.GridLayout4, 'push');
            app.SmoothdataButton.ButtonPushedFcn = createCallbackFcn(app, @SmoothdataButtonPushed, true);
            app.SmoothdataButton.Text = 'Smooth data';
            app.MapcolorsDropDownLabel = uilabel(app.GridLayout4);
            app.MapcolorsDropDownLabel.Text = 'Map colors';
            app.MapcolorsDropDown = uidropdown(app.GridLayout4);
            app.MapcolorsDropDown.Items = {'', 'jet', 'hot', 'turbo', 'parula', 'bone', 'gray'};
            app.MapcolorsDropDown.ValueChangedFcn = createCallbackFcn(app, @MapcolorsDropDownValueChanged, true);
            app.MapcolorsDropDown.Value = '';
            app.EditField = uieditfield(app.GridLayout4, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.WnfilterButton = uibutton(app.GridLayout4, 'push');
            app.WnfilterButton.ButtonPushedFcn = createCallbackFcn(app, @WnfilterButtonPushed, true);
            app.WnfilterButton.Text = 'Wn filter';
            app.Tree = uitree(app.GridLayout);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Node = uitreenode(app.Tree);
            app.Node.Text = 'Node';
            app.Node2 = uitreenode(app.Node);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.Node);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.Node);
            app.Node4.Text = 'Node4';
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.FinishButton = uibutton(app.GridLayout5, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.CancelButton = uibutton(app.GridLayout5, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SaveimagesButton = uibutton(app.GridLayout5, 'push');
            app.SaveimagesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveimagesButtonPushed, true);
            app.SaveimagesButton.Text = 'Save images';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sg_smooth

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end