classdef single_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        FinishButton               matlab.ui.control.Button
        TextArea                   matlab.ui.control.TextArea
        Tree                       matlab.ui.container.Tree
        Node                       matlab.ui.container.TreeNode
        Node2                      matlab.ui.container.TreeNode
        Node3                      matlab.ui.container.TreeNode
        Node4                      matlab.ui.container.TreeNode
        ClassificationPanel        matlab.ui.container.Panel
        GridLayout6_2              matlab.ui.container.GridLayout
        iButton_8                  matlab.ui.control.Button
        scorepairsDropDown_2       matlab.ui.control.DropDown
        scorepairsDropDown_2Label  matlab.ui.control.Label
        iButton_7                  matlab.ui.control.Button
        MethodsDropDown            matlab.ui.control.DropDown
        MethodsDropDownLabel       matlab.ui.control.Label
        loadingsDropDown_2         matlab.ui.control.DropDown
        loadingsDropDown_2Label    matlab.ui.control.Label
        UIAxes_10                  matlab.ui.control.UIAxes
        UIAxes_9                   matlab.ui.control.UIAxes
        Mainaxis1Panel             matlab.ui.container.Panel
        GridLayout8                matlab.ui.container.GridLayout
        iButton_5                  matlab.ui.control.Button
        MapmodesDropDownLabel      matlab.ui.control.Label
        MapmodesDropDown           matlab.ui.control.DropDown
        UIAxes_7                   matlab.ui.control.UIAxes
        RatioPanel                 matlab.ui.container.Panel
        GridLayout7                matlab.ui.container.GridLayout
        iButton_4                  matlab.ui.control.Button
        modesDropDown              matlab.ui.control.DropDown
        modesDropDownLabel         matlab.ui.control.Label
        UIAxes_4                   matlab.ui.control.UIAxes
        PCAPanel                   matlab.ui.container.Panel
        GridLayout6                matlab.ui.container.GridLayout
        iButton_3                  matlab.ui.control.Button
        iButton                    matlab.ui.control.Button
        loadingsDropDown           matlab.ui.control.DropDown
        loadingsDropDownLabel      matlab.ui.control.Label
        scorepairsDropDown         matlab.ui.control.DropDown
        scorepairsDropDownLabel    matlab.ui.control.Label
        UIAxes_3                   matlab.ui.control.UIAxes
        UIAxes_2                   matlab.ui.control.UIAxes
        GridLayout5                matlab.ui.container.GridLayout
        GridLayout3                matlab.ui.container.GridLayout
        Mainaxis2Panel             matlab.ui.container.Panel
        GridLayout9                matlab.ui.container.GridLayout
        iButton_6                  matlab.ui.control.Button
        MapmodesDropDown_2         matlab.ui.control.DropDown
        MapmodesDropDown_2Label    matlab.ui.control.Label
        UIAxes_8                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        AnalysisPayload = struct()
        Wavenumber double = []
        Spectra double = []
        GroupLabels = []
        GroupNames cell = {}
        DefineRange double = []
        SelectedROIs struct = struct('ID',{},'StartWN',{},'EndWN',{},'Label',{},'Active',{},'Patch',{})
        NextROIID double = 1
        ActiveROIID double = NaN
        ROIBeingDrawn logical = false
        ROIDrawStartX double = NaN
        ROIPreview = []
        ROIInteractiveObject = []
        PCAResult = struct()
        RatioResult = struct()
        ClassificationResult = struct()
        IsUpdatingAnalysis logical = false
        PinnedView = struct()
        SelectedPCALoadings double = 1
        SelectedClassLoadings double = 1
        LoadingOffsetFraction double = 0.22
        AxisPaddingFraction double = 0.07
        SupportApp = []
        SupportContext string = " "
        OriginalGroupLabels = []
        OriginalGroupNames cell = {}
        GroupDefinition struct = struct('Name',{},'SourceGroups',{})
        ShowPCAClusterEllipses logical = false
        ShowClassClusterEllipses logical = false
        SupportContextOrder string = ["PCA_SCORE","PCA_LOADING","RATIO","MAIN_AXIS_1","MAIN_AXIS_2","CLASS_SCORE","CLASS_LOADING"]
    end
    
    
    methods (Access = public)
        function loadAnalysisPayload(app,payload)
            app.initializeAnalysisWorkspace();
            app.receiveAnalysisPayload(payload);
            app.UIFigure.Visible = 'on';
            try
                app.UIFigure.WindowState = 'normal';
            catch
            end
            figure(app.UIFigure);
            drawnow;
        end
        
        function applySupportCommand(app,context,command,value)
            context = upper(string(context));
            command = upper(string(command));
            switch command
                case "SCORE_DOT"
                    app.applySupportScoreDot(context,value);
                case "COLORMAP"
                    app.applySupportColormap(context,value);
                case "NEXT_CONTEXT"
                    app.navigateSupportContext(context,1);
                case "PREVIOUS_CONTEXT"
                    app.navigateSupportContext(context,-1);
                case "COMPARE_MODE"
                    app.applySupportCompareMode(context);
                case "EDITABLE_PLOT"
                    app.applySupportEditablePlot(context);
                case "CLUSTER_LOCATES"
                    app.applySupportClusterLocate(context);
                case "RATIO_SETTINGS"
                    app.applySupportRatioSettings(context);
                case "NAME_GROUPS"
                    app.applySupportNameGroups(context);
                case "SPATIAL_SETTINGS"
                    app.applySupportSpatialSettings(context);
            end
        end
        
    end
    
    methods (Access = private)
        function startupFcn(app, payload)
            app.initializeAnalysisWorkspace();
            if nargin >= 2 && ~isempty(payload)
                app.receiveAnalysisPayload(payload);
            end
        end
        function initializeAnalysisWorkspace(app)
            app.MapmodesDropDown_2.Items = {'Mean spectra','All spectra'};
            app.MapmodesDropDown_2.Value = 'Mean spectra';
            app.MapmodesDropDown.Items = {'Pinned working spectra','Pinned PCA scores','Pinned PCA loadings','Pinned ratio','Pinned classification'};
            app.MapmodesDropDown.Value = 'Pinned working spectra';
            app.MethodsDropDown.Items = {'LDA','SVM'};
            app.MethodsDropDown.Value = 'LDA';
            app.scorepairsDropDown.Items = {'PC1 vs PC2'};
            app.scorepairsDropDown.Value = 'PC1 vs PC2';
            app.loadingsDropDown.Items = {'PC1'};
            app.loadingsDropDown.Value = 'PC1';
            app.modesDropDown.Items = {'No ratio'};
            app.modesDropDown.Value = 'No ratio';
            app.scorepairsDropDown_2.Items = {'Confusion matrix'};
            app.scorepairsDropDown_2.Value = 'Confusion matrix';
            app.loadingsDropDown_2.Items = {'Model summary'};
            app.loadingsDropDown_2.Value = 'Model summary';
            app.TextArea.Editable = 'off';
            app.UIAxes_8.ButtonDownFcn = createCallbackFcn(app,@UIAxes_8ButtonDown,true);
            app.UIFigure.WindowButtonMotionFcn = [];
            app.UIFigure.WindowButtonUpFcn = [];
            app.UIFigure.WindowButtonDownFcn = [];
            cla(app.UIAxes_7);
            cla(app.UIAxes_8);
            cla(app.UIAxes_2);
            cla(app.UIAxes_3);
            cla(app.UIAxes_4);
            cla(app.UIAxes_9);
            cla(app.UIAxes_10);
            title(app.UIAxes_8,'Working spectra');
            xlabel(app.UIAxes_8,'Raman shift (cm^{-1})');
            ylabel(app.UIAxes_8,'Intensity');
            title(app.UIAxes_7,'Pinned reference');
            xlabel(app.UIAxes_7,'Raman shift (cm^{-1})');
            ylabel(app.UIAxes_7,'Intensity');
            app.refreshAnalysisTree();
            app.updateContextText('Ready. Load an analysis payload to begin.');
        end
        function receiveAnalysisPayload(app,payload)
            app.validateAnalysisPayload(payload);
            app.AnalysisPayload = payload;
            app.Wavenumber = double(payload.Wavenumber(:));
            app.Spectra = double(payload.Spectra);
            if size(app.Spectra,2) ~= numel(app.Wavenumber) && size(app.Spectra,1) == numel(app.Wavenumber)
                app.Spectra = app.Spectra.';
            end
            app.GroupLabels = string(payload.GroupLabels(:));
            if isfield(payload,'GroupNames') && ~isempty(payload.GroupNames)
                app.GroupNames = cellstr(string(payload.GroupNames));
            else
                app.GroupNames = cellstr(unique(app.GroupLabels,'stable'));
            end
            app.OriginalGroupLabels = app.GroupLabels;
            app.OriginalGroupNames = app.GroupNames;
            app.GroupDefinition = struct('Name',{},'SourceGroups',{});
            originalNames = unique(app.OriginalGroupLabels,'stable');
            for g = 1:numel(originalNames)
                app.GroupDefinition(g).Name = char(originalNames(g));
                app.GroupDefinition(g).SourceGroups = originalNames(g);
            end
            if isfield(payload,'DefineRange') && numel(payload.DefineRange) == 2 && all(isfinite(payload.DefineRange))
                app.DefineRange = sort(double(payload.DefineRange(:)'));
            else
                app.DefineRange = [min(app.Wavenumber) max(app.Wavenumber)];
            end
            app.DefineRange(1) = max(app.DefineRange(1),min(app.Wavenumber));
            app.DefineRange(2) = min(app.DefineRange(2),max(app.Wavenumber));
            app.SelectedPCALoadings = 1;
            app.SelectedClassLoadings = 1;
            app.ShowPCAClusterEllipses = false;
            app.ShowClassClusterEllipses = false;
            app.clearAllROIs(false);
            app.renderWorkingAxis();
            app.pinWorkingAxis();
            app.refreshAnalysisTree();
            app.updateContextText(sprintf(['Dataset loaded\nSpectra: %d\nVariables: %d\nOriginal groups: %d\nAnalysis groups: %d\nDefined Raman domain: %.2f - %.2f cm^-1'],size(app.Spectra,1),size(app.Spectra,2),numel(unique(app.OriginalGroupLabels)),numel(unique(app.GroupLabels)),app.DefineRange(1),app.DefineRange(2)));
        end
        function validateAnalysisPayload(app, payload)
            if ~isstruct(payload)
                error('Analysis payload must be a struct.');
            end
            required = {'Wavenumber','Spectra','GroupLabels'};
            for k = 1:numel(required)
                if ~isfield(payload,required{k})
                    error('Analysis payload is missing field "%s".',required{k});
                end
            end
            wn = payload.Wavenumber(:);
            X = payload.Spectra;
            labels = payload.GroupLabels(:);
            if isempty(wn) || isempty(X)
                error('Wavenumber and Spectra cannot be empty.');
            end
            if size(X,2) ~= numel(wn) && size(X,1) ~= numel(wn)
                error('One dimension of Spectra must equal the number of wavenumber values.');
            end
            if size(X,2) == numel(wn)
                nSpectra = size(X,1);
            else
                nSpectra = size(X,2);
            end
            if numel(labels) ~= nSpectra
                error('GroupLabels length must equal the number of spectra.');
            end
            if any(~isfinite(wn))
                error('Wavenumber contains non-finite values.');
            end
        end
        
        function idx = groupMask(app, labels, target)
            if isnumeric(labels) || islogical(labels)
                idx = labels == target;
            elseif iscategorical(labels)
                idx = labels == target;
            elseif isstring(labels)
                idx = labels == target;
            elseif iscell(labels)
                idx = strcmp(string(labels),string(target));
            else
                idx = string(labels) == string(target);
            end
        end
        function beginROIDraw(app)
            if isempty(app.Wavenumber)
                return
            end
            yl = app.UIAxes_8.YLim;
            try
                roi = drawrectangle( ...
                    app.UIAxes_8, ...
                    'DrawingArea',[app.DefineRange(1) yl(1) diff(app.DefineRange) diff(yl)], ...
                    'FaceAlpha',0.08, ...
                    'LineWidth',1.25, ...
                    'InteractionsAllowed','all');
            catch ME
                uialert(app.UIFigure,ME.message,'ROI drawing error');
                return
            end
            if isempty(roi) || ~isvalid(roi)
                return
            end
            pos = double(roi.Position);
            a = max(app.DefineRange(1),pos(1));
            b = min(app.DefineRange(2),pos(1)+pos(3));
            try
                delete(roi);
            catch
            end
            if b-a < app.getMinimumROIWidth()
                return
            end
            app.addSpectralROI(a,b,'');
        end
        function updateROIDrawPreview(app)
            if ~app.ROIBeingDrawn || isempty(app.ROIPreview) || ~isvalid(app.ROIPreview)
                return
            end
            cp = app.UIAxes_8.CurrentPoint;
            x2 = cp(1,1);
            xl = xlim(app.UIAxes_8);
            x2 = max(xl(1),min(xl(2),x2));
            x1 = app.ROIDrawStartX;
            yl = ylim(app.UIAxes_8);
            app.ROIPreview.XData = [x1 x2 x2 x1];
            app.ROIPreview.YData = [yl(1) yl(1) yl(2) yl(2)];
            drawnow limitrate
        end
        function finishROIDraw(app)
            if ~app.ROIBeingDrawn
                return
            end
            app.ROIBeingDrawn = false;
            app.UIFigure.WindowButtonMotionFcn = [];
            app.UIFigure.WindowButtonUpFcn = [];
            cp = app.UIAxes_8.CurrentPoint;
            x2 = cp(1,1);
            x1 = app.ROIDrawStartX;
            if ~isempty(app.ROIPreview) && isvalid(app.ROIPreview)
                delete(app.ROIPreview);
            end
            app.ROIPreview = [];
            app.ROIDrawStartX = NaN;
            xl = xlim(app.UIAxes_8);
            x1 = max(xl(1),min(xl(2),x1));
            x2 = max(xl(1),min(xl(2),x2));
            a = min(x1,x2);
            b = max(x1,x2);
            if abs(b-a) <= max(eps,0.001*range(app.Wavenumber))
                return
            end
            app.addSpectralROI(a,b,'');
        end
        
        function menu = createROIContextMenu(app,roiID)
            menu = uicontextmenu(app.UIFigure);
            uimenu(menu,'Text','Edit by values','MenuSelectedFcn',@(src,event)app.editROIByID(roiID));
            uimenu(menu,'Text','Edit by dragging','MenuSelectedFcn',@(src,event)app.editROIInteractively(roiID));
            uimenu(menu,'Text','Rename ROI','Separator','on','MenuSelectedFcn',@(src,event)app.renameROIByID(roiID));
            uimenu(menu,'Text','Enable / Disable','MenuSelectedFcn',@(src,event)app.toggleROIByID(roiID));
            uimenu(menu,'Text','Delete ROI','Separator','on','MenuSelectedFcn',@(src,event)app.deleteROIByID(roiID));
        end
        function roiPatchClicked(app,src,event)
            if isempty(src) || ~isvalid(src)
                return
            end
            roiID = src.UserData;
            app.ActiveROIID = roiID;
            if strcmp(app.UIFigure.SelectionType,'open')
                app.showROIActionDialog(roiID);
                return
            end
            app.updateContextForActiveROI();
            app.refreshAnalysisTree();
        end
        function editROIInteractively(app,roiID)
            idx = app.findROIIndex(roiID);
            if isempty(idx)
                return
            end
            R = app.SelectedROIs(idx);
            yl = ylim(app.UIAxes_8);
            if ~isempty(app.ROIInteractiveObject)
                try
                    if isvalid(app.ROIInteractiveObject)
                        delete(app.ROIInteractiveObject);
                    end
                catch
                end
                app.ROIInteractiveObject = [];
            end
            try
                app.ROIInteractiveObject = drawrectangle( ...
                    app.UIAxes_8, ...
                    'Position',[R.StartWN yl(1) R.EndWN-R.StartWN yl(2)-yl(1)], ...
                    'DrawingArea',[app.DefineRange(1) yl(1) diff(app.DefineRange) diff(yl)], ...
                    'FaceAlpha',0.10, ...
                    'LineWidth',1.4, ...
                    'Label',R.Label);
                finalPosition = wait(app.ROIInteractiveObject);
            catch ME
                try
                    if ~isempty(app.ROIInteractiveObject) && isvalid(app.ROIInteractiveObject)
                        delete(app.ROIInteractiveObject);
                    end
                catch
                end
                app.ROIInteractiveObject = [];
                uialert(app.UIFigure,ME.message,'ROI edit error');
                return
            end
            if isempty(finalPosition) || numel(finalPosition) < 4
                try
                    if ~isempty(app.ROIInteractiveObject) && isvalid(app.ROIInteractiveObject)
                        delete(app.ROIInteractiveObject);
                    end
                catch
                end
                app.ROIInteractiveObject = [];
                app.redrawROIs();
                return
            end
            app.finishInteractiveROIEdit(roiID,finalPosition);
        end
        function finishInteractiveROIEdit(app,roiID,pos)
            idx = app.findROIIndex(roiID);
            if isempty(idx)
                return
            end
            pos = double(pos);
            x1 = pos(1);
            x2 = pos(1)+pos(3);
            a = max(app.DefineRange(1),min(x1,x2));
            b = min(app.DefineRange(2),max(x1,x2));
            try
                if ~isempty(app.ROIInteractiveObject) && isvalid(app.ROIInteractiveObject)
                    delete(app.ROIInteractiveObject);
                end
            catch
            end
            app.ROIInteractiveObject = [];
            if ~isfinite(a) || ~isfinite(b) || b <= a
                app.redrawROIs();
                return
            end
            app.SelectedROIs(idx).StartWN = a;
            app.SelectedROIs(idx).EndWN = b;
            app.ActiveROIID = roiID;
            app.redrawROIs();
            app.onAnalysisSelectionChanged();
        end
        function enterROINumerically(app)
            if isempty(app.Wavenumber)
                return
            end
            cp = app.UIAxes_8.CurrentPoint;
            centerWN = min(max(cp(1,1),app.DefineRange(1)),app.DefineRange(2));
            defaultWidth = max(10,0.02*diff(app.DefineRange));
            a0 = max(app.DefineRange(1),centerWN-defaultWidth/2);
            b0 = min(app.DefineRange(2),centerWN+defaultWidth/2);
            answer = inputdlg({'Start wavenumber (cm^-1)','End wavenumber (cm^-1)','ROI label'},'Add spectral ROI',[1 45],{sprintf('%.2f',a0),sprintf('%.2f',b0),sprintf('ROI %d',app.NextROIID)});
            if isempty(answer)
                return
            end
            a = str2double(answer{1});
            b = str2double(answer{2});
            if ~isfinite(a) || ~isfinite(b) || a == b
                uialert(app.UIFigure,'Invalid ROI range.','ROI');
                return
            end
            app.addSpectralROI(a,b,answer{3});
        end
        
        function renameROIByID(app, roiID)
            idx = app.findROIIndex(roiID);
            if isempty(idx)
                return
            end
            answer = inputdlg({'ROI label'},'Rename ROI',[1 40],{app.SelectedROIs(idx).Label});
            if isempty(answer)
                return
            end
            app.SelectedROIs(idx).Label = char(string(answer{1}));
            app.refreshAnalysisTree();
            app.updateContextForActiveROI();
        end
        function redrawROIs(app)
            if isempty(app.SelectedROIs) || isempty(app.Wavenumber)
                return
            end
            yl = app.UIAxes_8.YLim;
            for k = 1:numel(app.SelectedROIs)
                R = app.SelectedROIs(k);
                if ~R.Active
                    try
                        if ~isempty(R.Patch) && isvalid(R.Patch)
                            delete(R.Patch);
                        end
                    catch
                    end
                    app.SelectedROIs(k).Patch = [];
                    continue
                end
                existingValid = false;
                try
                    existingValid = ~isempty(R.Patch) && isvalid(R.Patch) && isa(R.Patch,'images.roi.Rectangle');
                catch
                    existingValid = false;
                end
                if existingValid
                    app.SelectedROIs(k).Patch.Position = [R.StartWN yl(1) R.EndWN-R.StartWN diff(yl)];
                    continue
                end
                try
                    if ~isempty(R.Patch) && isvalid(R.Patch)
                        delete(R.Patch);
                    end
                catch
                end
                roi = drawrectangle( ...
                    app.UIAxes_8, ...
                    'Position',[R.StartWN yl(1) R.EndWN-R.StartWN diff(yl)], ...
                    'DrawingArea',[app.DefineRange(1) yl(1) diff(app.DefineRange) diff(yl)], ...
                    'FaceAlpha',0.08, ...
                    'LineWidth',1.25, ...
                    'Label',R.Label, ...
                    'InteractionsAllowed','all');
                roi.UserData = R.ID;
                roi.ContextMenu = app.createROIContextMenu(R.ID);
                addlistener(roi,'MovingROI',@(src,event)app.roiMoving(src));
                addlistener(roi,'ROIMoved',@(src,event)app.roiMoved(src));
                addlistener(roi,'ROIClicked',@(src,event)app.roiObjectClicked(src));
                app.SelectedROIs(k).Patch = roi;
            end
        end
        function roiMoving(app,src)
            if isempty(src) || ~isvalid(src)
                return
            end
            pos = double(src.Position);
            if numel(pos) < 4
                return
            end
            yl = app.UIAxes_8.YLim;
            x1 = pos(1);
            x2 = pos(1)+pos(3);
            a = max(app.DefineRange(1),min(x1,x2));
            b = min(app.DefineRange(2),max(x1,x2));
            minWidth = app.getMinimumROIWidth();
            if b-a < minWidth
                b = min(app.DefineRange(2),a+minWidth);
            end
            src.Position = [a yl(1) b-a diff(yl)];
        end
        function roiMoved(app,src)
            if isempty(src) || ~isvalid(src)
                return
            end
            roiID = src.UserData;
            idx = app.findROIIndex(roiID);
            if isempty(idx)
                return
            end
            pos = double(src.Position);
            x1 = pos(1);
            x2 = pos(1)+pos(3);
            a = max(app.DefineRange(1),min(x1,x2));
            b = min(app.DefineRange(2),max(x1,x2));
            if b-a < app.getMinimumROIWidth()
                return
            end
            yl = app.UIAxes_8.YLim;
            src.Position = [a yl(1) b-a diff(yl)];
            app.SelectedROIs(idx).StartWN = a;
            app.SelectedROIs(idx).EndWN = b;
            app.SelectedROIs(idx).Patch = src;
            app.ActiveROIID = roiID;
            app.onAnalysisSelectionChanged();
        end
        function roiObjectClicked(app,src)
            if isempty(src) || ~isvalid(src)
                return
            end
            roiID = src.UserData;
            app.ActiveROIID = roiID;
            if strcmp(app.UIFigure.SelectionType,'open')
                app.showROIActionDialog(roiID);
                return
            end
            app.updateContextForActiveROI();
        end
        function showROIActionDialog(app,roiID)
            idx = app.findROIIndex(roiID);
            if isempty(idx)
                return
            end
            R = app.SelectedROIs(idx);
            choice = uiconfirm( ...
                app.UIFigure, ...
                sprintf('%s\n%.2f - %.2f cm^{-1}',R.Label,R.StartWN,R.EndWN), ...
                'ROI', ...
                'Options',{'Edit values','Delete','Cancel'}, ...
                'DefaultOption',1, ...
                'CancelOption',3);
            switch choice
                case 'Edit values'
                    app.editROIByID(roiID);
                case 'Delete'
                    app.deleteROIByID(roiID);
            end
        end
        function width = getMinimumROIWidth(app)
            d = abs(diff(app.Wavenumber));
            d = d(isfinite(d) & d > 0);
            if isempty(d)
                width = 1;
            else
                width = max(median(d,'omitnan'),1);
            end
        end
        function addSpectralROI(app,startWN,endWN,label)
            if isempty(app.Wavenumber)
                return
            end
            a = max(app.DefineRange(1),min(startWN,endWN));
            b = min(app.DefineRange(2),max(startWN,endWN));
            if ~isfinite(a) || ~isfinite(b) || b-a < app.getMinimumROIWidth()
                return
            end
            if nargin < 4 || strlength(strtrim(string(label))) == 0
                label = sprintf('ROI %d',app.NextROIID);
            end
            R.ID = app.NextROIID;
            R.StartWN = a;
            R.EndWN = b;
            R.Label = char(string(label));
            R.Active = true;
            R.Patch = [];
            app.NextROIID = app.NextROIID+1;
            app.SelectedROIs(end+1) = R;
            app.ActiveROIID = R.ID;
            app.redrawROIs();
            app.onAnalysisSelectionChanged();
        end
        function editROIByID(app,roiID)
            idx = app.findROIIndex(roiID);
            if isempty(idx)
                return
            end
            R = app.SelectedROIs(idx);
            answer = inputdlg( ...
                {'Start wavenumber (cm^-1)','End wavenumber (cm^-1)','ROI label'}, ...
                'Edit spectral ROI', ...
                [1 45], ...
                {sprintf('%.2f',R.StartWN),sprintf('%.2f',R.EndWN),R.Label});
            if isempty(answer)
                return
            end
            a = str2double(answer{1});
            b = str2double(answer{2});
            if ~isfinite(a) || ~isfinite(b) || a == b
                uialert(app.UIFigure,'Invalid ROI range.','ROI');
                return
            end
            a = max(app.DefineRange(1),min(a,b));
            b = min(app.DefineRange(2),max(a,b));
            if b-a < app.getMinimumROIWidth()
                uialert(app.UIFigure,'ROI is too narrow or outside the defined Raman domain.','ROI');
                return
            end
            app.SelectedROIs(idx).StartWN = a;
            app.SelectedROIs(idx).EndWN = b;
            app.SelectedROIs(idx).Label = char(string(answer{3}));
            app.ActiveROIID = roiID;
            try
                roi = app.SelectedROIs(idx).Patch;
                if ~isempty(roi) && isvalid(roi)
                    yl = app.UIAxes_8.YLim;
                    roi.Position = [a yl(1) b-a diff(yl)];
                    roi.Label = app.SelectedROIs(idx).Label;
                end
            catch
            end
            app.onAnalysisSelectionChanged();
        end
        function deleteROIByID(app,roiID)
            idx = app.findROIIndex(roiID);
            if isempty(idx)
                return
            end
            try
                roi = app.SelectedROIs(idx).Patch;
                if ~isempty(roi) && isvalid(roi)
                    delete(roi);
                end
            catch
            end
            app.SelectedROIs(idx) = [];
            if app.ActiveROIID == roiID
                app.ActiveROIID = NaN;
            end
            app.onAnalysisSelectionChanged();
        end
        function toggleROIByID(app, roiID)
            idx = app.findROIIndex(roiID);
            if isempty(idx)
                return
            end
            app.SelectedROIs(idx).Active = ~app.SelectedROIs(idx).Active;
            app.redrawROIs();
            app.onAnalysisSelectionChanged();
        end
        function clearAllROIs(app, updateAnalysis)
            if nargin < 2
                updateAnalysis = true;
            end
            for k = 1:numel(app.SelectedROIs)
                try
                    if ~isempty(app.SelectedROIs(k).Patch) && isvalid(app.SelectedROIs(k).Patch)
                        delete(app.SelectedROIs(k).Patch);
                    end
                catch
                end
            end
            app.SelectedROIs = struct('ID',{},'StartWN',{},'EndWN',{},'Label',{},'Active',{},'Patch',{});
            app.NextROIID = 1;
            app.ActiveROIID = NaN;
            if updateAnalysis
                app.onAnalysisSelectionChanged();
            end
        end
        function idx = findROIIndex(app, roiID)
            idx = find([app.SelectedROIs.ID] == roiID,1,'first');
        end
        function updateContextForActiveROI(app)
            idx = app.findROIIndex(app.ActiveROIID);
            if isempty(idx)
                return
            end
            R = app.SelectedROIs(idx);
            app.updateContextText(sprintf('%s\nRange: %.2f - %.2f cm^-1\nWidth: %.2f cm^-1\nActive: %s',R.Label,R.StartWN,R.EndWN,R.EndWN-R.StartWN,string(R.Active)));
        end
        function mask = getActiveSpectralMask(app)
            mask = false(size(app.Wavenumber));
            for k = 1:numel(app.SelectedROIs)
                if app.SelectedROIs(k).Active
                    mask = mask | (app.Wavenumber >= app.SelectedROIs(k).StartWN & app.Wavenumber <= app.SelectedROIs(k).EndWN);
                end
            end
        end
        function active = getActiveROIs(app)
            if isempty(app.SelectedROIs)
                active = app.SelectedROIs;
                return
            end
            active = app.SelectedROIs([app.SelectedROIs.Active]);
        end
        function onAnalysisSelectionChanged(app)
            if app.IsUpdatingAnalysis
                return
            end
            app.IsUpdatingAnalysis = true;
            cleanup = onCleanup(@()app.releaseAnalysisUpdateLock());
            app.refreshAnalysisTree();
            active = app.getActiveROIs();
            if isempty(active)
                app.PCAResult = struct();
                app.RatioResult = struct();
                app.ClassificationResult = struct();
                cla(app.UIAxes_2);
                cla(app.UIAxes_3);
                cla(app.UIAxes_4);
                cla(app.UIAxes_9);
                cla(app.UIAxes_10);
                title(app.UIAxes_3,'PCA scores');
                title(app.UIAxes_2,'PCA loadings');
                title(app.UIAxes_4,'Ratio');
                title(app.UIAxes_9,'Classification');
                title(app.UIAxes_10,'Model');
                app.modesDropDown.Items = {'No ratio'};
                app.modesDropDown.Value = 'No ratio';
                app.updateContextText('No active spectral ROI.\nDrag on Working Axis 2 or double-click to enter a wavenumber range.');
                return
            end
            app.computeLivePCA();
            app.computeLiveRatios();
            app.computeLiveClassification();
            app.refreshAnalysisTree();
            app.updateAnalysisSummary();
        end
        function releaseAnalysisUpdateLock(app)
            app.IsUpdatingAnalysis = false;
        end
        function computeLivePCA(app)
            spectralMask = app.getActiveSpectralMask();
            if nnz(spectralMask) < 2 || size(app.Spectra,1) < 2
                app.PCAResult = struct();
                cla(app.UIAxes_2);
                cla(app.UIAxes_3);
                return
            end
            labelsAll = string(app.GroupLabels(:));
            validGroup = strlength(strtrim(labelsAll)) > 0;
            X = app.Spectra(:,spectralMask);
            validRows = all(isfinite(X),2) & validGroup;
            Xv = X(validRows,:);
            labels = labelsAll(validRows);
            if size(Xv,1) < 2
                return
            end
            [coeff,score,latent,~,explained] = pca(Xv);
            nPC = min([size(score,2),size(coeff,2),10]);
            app.PCAResult.Mask = spectralMask;
            app.PCAResult.Coeff = coeff;
            app.PCAResult.Score = score;
            app.PCAResult.Latent = latent;
            app.PCAResult.Explained = explained;
            app.PCAResult.Labels = labels;
            app.PCAResult.ValidRows = validRows;
            scoreItems = {};
            for i = 1:nPC-1
                for j = i+1:nPC
                    scoreItems{end+1} = sprintf('PC%d vs PC%d',i,j); %#ok<AGROW>
                end
            end
            if isempty(scoreItems)
                scoreItems = {'PC1'};
            end
            app.scorepairsDropDown.Items = scoreItems;
            if ~ismember(string(app.scorepairsDropDown.Value),string(scoreItems))
                app.scorepairsDropDown.Value = scoreItems{1};
            end
            loadingItems = [arrayfun(@(k)sprintf('PC%d',k),1:nPC,'UniformOutput',false),{'Select multiple...'}];
            app.loadingsDropDown.Items = loadingItems;
            if isempty(app.SelectedPCALoadings) || any(app.SelectedPCALoadings > nPC)
                app.SelectedPCALoadings = 1;
            end
            app.loadingsDropDown.Value = sprintf('PC%d',app.SelectedPCALoadings(1));
            app.plotPCAScores();
            app.plotPCALoadings();
        end
        function plotPCAScores(app)
            cla(app.UIAxes_3);
            if isempty(fieldnames(app.PCAResult))
                return
            end
            token = regexp(char(app.scorepairsDropDown.Value),'PC(\d+)\s+vs\s+PC(\d+)','tokens','once');
            if isempty(token)
                return
            end
            pc1 = str2double(token{1});
            pc2 = str2double(token{2});
            score = app.PCAResult.Score;
            labels = string(app.PCAResult.Labels);
            groups = unique(labels,'stable');
            hold(app.UIAxes_3,'on');
            for g = 1:numel(groups)
                idx = app.groupMask(labels,groups(g));
                scatter(app.UIAxes_3,score(idx,pc1),score(idx,pc2),26,'filled','DisplayName',char(groups(g)));
            end
            ellipseX = [];
            ellipseY = [];
            if app.ShowPCAClusterEllipses
                [ellipseX,ellipseY] = app.drawScoreGroupEllipses(app.UIAxes_3,score(:,pc1),score(:,pc2),labels);
            end
            hold(app.UIAxes_3,'off');
            xlabel(app.UIAxes_3,sprintf('PC%d (%.1f%%)',pc1,app.PCAResult.Explained(pc1)));
            ylabel(app.UIAxes_3,sprintf('PC%d (%.1f%%)',pc2,app.PCAResult.Explained(pc2)));
            title(app.UIAxes_3,'PCA scores');
            app.UIAxes_3.Box = 'on';
            app.UIAxes_3.TickDir = 'out';
            app.applyScoreLimitsWithEllipses(app.UIAxes_3,score(:,pc1),score(:,pc2),ellipseX,ellipseY);
        end
        
        function computeLiveRatios(app)
            active = app.getActiveROIs();
            cla(app.UIAxes_4);
            if numel(active) < 2
                app.RatioResult = struct();
                app.modesDropDown.Items = {'Need at least 2 ROIs'};
                app.modesDropDown.Value = 'Need at least 2 ROIs';
                title(app.UIAxes_4,'Ratio');
                return
            end
            nROI = numel(active);
            bandValues = nan(size(app.Spectra,1),nROI);
            for r = 1:nROI
                idx = app.Wavenumber >= active(r).StartWN & app.Wavenumber <= active(r).EndWN;
                if nnz(idx) >= 2
                    bandValues(:,r) = trapz(app.Wavenumber(idx),app.Spectra(:,idx),2);
                elseif nnz(idx) == 1
                    bandValues(:,r) = app.Spectra(:,idx);
                end
            end
            ratioNames = {};
            ratioValues = {};
            pairIDs = [];
            c = 0;
            for i = 1:nROI
                for j = 1:nROI
                    if i == j
                        continue
                    end
                    c = c+1;
                    ratioNames{c} = sprintf('%s / %s',active(i).Label,active(j).Label);
                    den = bandValues(:,j);
                    y = bandValues(:,i)./den;
                    y(~isfinite(y)) = NaN;
                    ratioValues{c} = y;
                    pairIDs(c,:) = [active(i).ID active(j).ID];
                end
            end
            app.RatioResult.BandValues = bandValues;
            app.RatioResult.Names = ratioNames;
            app.RatioResult.Values = ratioValues;
            app.RatioResult.PairIDs = pairIDs;
            app.modesDropDown.Items = ratioNames;
            if ~ismember(app.modesDropDown.Value,ratioNames)
                app.modesDropDown.Value = ratioNames{1};
            end
            app.plotSelectedRatio();
        end
        function plotSelectedRatio(app)
            cla(app.UIAxes_4,'reset');
            if isempty(fieldnames(app.RatioResult))
                return
            end
            idxRatio = find(strcmp(app.RatioResult.Names,app.modesDropDown.Value),1,'first');
            if isempty(idxRatio)
                return
            end
            y = app.RatioResult.Values{idxRatio};
            labels = string(app.GroupLabels(:));
            validGroup = strlength(strtrim(labels)) > 0;
            groups = unique(labels(validGroup),'stable');
            hold(app.UIAxes_4,'on');
            plottedY = [];
            for g = 1:numel(groups)
                idx = validGroup & app.groupMask(labels,groups(g)) & isfinite(y);
                yg = y(idx);
                if isempty(yg)
                    continue
                end
                x = repmat(g,numel(yg),1);
                scatter(app.UIAxes_4,x,yg,24,'filled','jitter','on','jitterAmount',0.10);
                med = median(yg,'omitnan');
                plot(app.UIAxes_4,[g-0.22 g+0.22],[med med],'-','LineWidth',1.8);
                plottedY = [plottedY;yg(:)]; %#ok<AGROW>
            end
            hold(app.UIAxes_4,'off');
            app.UIAxes_4.XTick = 1:numel(groups);
            app.UIAxes_4.XTickLabel = cellstr(string(groups));
            xlim(app.UIAxes_4,[0.5 numel(groups)+0.5]);
            ylabel(app.UIAxes_4,'Integrated-area ratio');
            title(app.UIAxes_4,app.RatioResult.Names{idxRatio},'Interpreter','none');
            app.UIAxes_4.Box = 'on';
            app.UIAxes_4.TickDir = 'out';
            app.applyRobustYLimits(app.UIAxes_4,plottedY);
        end
        function computeLiveClassification(app)
            spectralMask = app.getActiveSpectralMask();
            cla(app.UIAxes_9);
            cla(app.UIAxes_10);
            if nnz(spectralMask) < 2
                app.ClassificationResult = struct();
                return
            end
            X = app.Spectra(:,spectralMask);
            Y = string(app.GroupLabels(:));
            validRows = all(isfinite(X),2) & strlength(strtrim(Y)) > 0;
            X = X(validRows,:);
            Y = Y(validRows);
            groups = unique(Y,'stable');
            if numel(groups) < 2 || size(X,1) < 4
                app.ClassificationResult = struct();
                title(app.UIAxes_9,'Classification requires at least two groups');
                return
            end
            method = string(app.MethodsDropDown.Value);
            switch method
                case "LDA"
                    app.computeLiveLDA(X,Y,spectralMask);
                case "SVM"
                    app.computeLiveSVM(X,Y,spectralMask);
            end
        end
        
        function computeLiveLDA(app,X,Y,spectralMask)
            model = fitcdiscr(X,Y,'DiscrimType','linear');
            groups = unique(Y,'stable');
            nClass = numel(groups);
            mu = mean(X,1);
            Sw = zeros(size(X,2));
            Sb = zeros(size(X,2));
            for g = 1:nClass
                idx = app.groupMask(Y,groups(g));
                Xg = X(idx,:);
                mg = mean(Xg,1);
                Xgc = Xg-mg;
                Sw = Sw + Xgc.'*Xgc;
                d = (mg-mu).';
                Sb = Sb + size(Xg,1)*(d*d.');
            end
            [V,D] = eig(Sb,Sw+eye(size(Sw))*eps);
            eigVal = real(diag(D));
            [eigVal,ord] = sort(eigVal,'descend');
            V = real(V(:,ord));
            nComp = min([nClass-1,size(V,2),5]);
            V = V(:,1:nComp);
            scores = (X-mu)*V;
            if size(scores,2) == 1
                scores(:,2) = zeros(size(scores,1),1);
            end
            app.ClassificationResult = struct();
            app.ClassificationResult.Method = 'LDA';
            app.ClassificationResult.Model = model;
            app.ClassificationResult.Scores = scores;
            app.ClassificationResult.Loadings = V;
            app.ClassificationResult.Eigenvalues = eigVal(1:nComp);
            app.ClassificationResult.Labels = Y;
            app.ClassificationResult.Mask = spectralMask;
            items = arrayfun(@(k)sprintf('LD%d',k),1:nComp,'UniformOutput',false);
            app.loadingsDropDown_2.Items = [items {'Select multiple...'}];
            if isempty(app.SelectedClassLoadings) || any(app.SelectedClassLoadings > nComp)
                app.SelectedClassLoadings = 1;
            end
            app.loadingsDropDown_2.Value = sprintf('LD%d',app.SelectedClassLoadings(1));
            if nComp >= 2
                app.scorepairsDropDown_2.Items = {'LD1 vs LD2'};
                app.scorepairsDropDown_2.Value = 'LD1 vs LD2';
            else
                app.scorepairsDropDown_2.Items = {'LD1'};
                app.scorepairsDropDown_2.Value = 'LD1';
            end
            app.plotClassificationScores();
            app.plotClassificationLoadings();
        end
        function computeLiveSVM(app,X,Y,spectralMask)
            base = templateSVM('Standardize',true,'KernelFunction','linear');
            model = fitcecoc(X,Y,'Learners',base,'Coding','onevsone');
            [coeff,scorePCA] = pca(X);
            scorePCA = scorePCA(:,1:min(2,size(scorePCA,2)));
            if size(scorePCA,2) == 1
                scorePCA(:,2) = zeros(size(scorePCA,1),1);
            end
            beta = zeros(size(X,2),numel(model.BinaryLearners));
            for k = 1:numel(model.BinaryLearners)
                learner = model.BinaryLearners{k};
                if isprop(learner,'Beta') && ~isempty(learner.Beta)
                    beta(:,k) = learner.Beta;
                end
            end
            app.ClassificationResult = struct();
            app.ClassificationResult.Method = 'SVM';
            app.ClassificationResult.Model = model;
            app.ClassificationResult.Scores = scorePCA;
            app.ClassificationResult.Loadings = beta;
            app.ClassificationResult.Labels = Y;
            app.ClassificationResult.Mask = spectralMask;
            app.scorepairsDropDown_2.Items = {'Display PC1 vs PC2'};
            app.scorepairsDropDown_2.Value = 'Display PC1 vs PC2';
            nWeight = size(beta,2);
            items = arrayfun(@(k)sprintf('Weight %d',k),1:nWeight,'UniformOutput',false);
            app.loadingsDropDown_2.Items = [items {'Select multiple...'}];
            app.SelectedClassLoadings = 1;
            app.loadingsDropDown_2.Value = items{1};
            app.plotClassificationScores();
            app.plotClassificationLoadings();
        end
        function plotClassificationScores(app)
            cla(app.UIAxes_9);
            if isempty(fieldnames(app.ClassificationResult)) || ~isfield(app.ClassificationResult,'Scores')
                return
            end
            S = app.ClassificationResult.Scores;
            Y = string(app.ClassificationResult.Labels);
            groups = unique(Y,'stable');
            hold(app.UIAxes_9,'on');
            for g = 1:numel(groups)
                idx = app.groupMask(Y,groups(g));
                scatter(app.UIAxes_9,S(idx,1),S(idx,2),26,'filled','DisplayName',char(groups(g)));
            end
            ellipseX = [];
            ellipseY = [];
            if app.ShowClassClusterEllipses
                [ellipseX,ellipseY] = app.drawScoreGroupEllipses(app.UIAxes_9,S(:,1),S(:,2),Y);
            end
            hold(app.UIAxes_9,'off');
            if strcmp(app.ClassificationResult.Method,'LDA')
                xlabel(app.UIAxes_9,'LD1');
                ylabel(app.UIAxes_9,'LD2');
                title(app.UIAxes_9,'LDA scores');
            else
                xlabel(app.UIAxes_9,'Display PC1');
                ylabel(app.UIAxes_9,'Display PC2');
                title(app.UIAxes_9,'SVM samples in PCA display space');
            end
            app.UIAxes_9.Box = 'on';
            app.UIAxes_9.TickDir = 'out';
            app.applyScoreLimitsWithEllipses(app.UIAxes_9,S(:,1),S(:,2),ellipseX,ellipseY);
        end
        function plotPCALoadings(app)
            cla(app.UIAxes_2);
            if isempty(fieldnames(app.PCAResult)) || ~isfield(app.PCAResult,'Mask')
                return
            end
            pcs = unique(round(app.SelectedPCALoadings(:)'),'stable');
            pcs = pcs(pcs >= 1 & pcs <= size(app.PCAResult.Coeff,2));
            if isempty(pcs)
                pcs = 1;
            end
            wn = app.Wavenumber(app.PCAResult.Mask);
            curves = app.PCAResult.Coeff(:,pcs);
            if isempty(wn) || isempty(curves)
                return
            end
            offsetStep = app.getSmartLoadingOffset(curves);
            allY = [];
            hold(app.UIAxes_2,'on');
            for k = 1:numel(pcs)
                baseline = (k-1)*offsetStep;
                y = curves(:,k)+baseline;
                yline(app.UIAxes_2,baseline,'--','HandleVisibility','off');
                plot(app.UIAxes_2,wn,y,'LineWidth',1.4,'DisplayName',sprintf('PC%d (%.1f%%)',pcs(k),app.PCAResult.Explained(pcs(k))));
                allY = [allY;y(:)]; %#ok<AGROW>
            end
            hold(app.UIAxes_2,'off');
            xlabel(app.UIAxes_2,'Raman shift (cm^{-1})');
            ylabel(app.UIAxes_2,'Loading + offset');
            title(app.UIAxes_2,'PCA loadings');
            app.UIAxes_2.Box = 'on';
            app.UIAxes_2.TickDir = 'out';
            xlim(app.UIAxes_2,[min(wn) max(wn)]);
            app.applyRobustYLimits(app.UIAxes_2,allY);
            legend(app.UIAxes_2,'show','Location','best','Box','off');
        end
        function plotClassificationLoadings(app)
            cla(app.UIAxes_10);
            if isempty(fieldnames(app.ClassificationResult)) || ...
                    ~isfield(app.ClassificationResult,'Loadings') || ...
                    ~isfield(app.ClassificationResult,'Mask')
                return
            end
            L = app.ClassificationResult.Loadings;
            selected = unique(round(app.SelectedClassLoadings(:)'),'stable');
            selected = selected(selected >= 1 & selected <= size(L,2));
            if isempty(selected)
                selected = 1;
            end
            wn = app.Wavenumber(app.ClassificationResult.Mask);
            curves = L(:,selected);
            if isempty(wn) || isempty(curves)
                return
            end
            offsetStep = app.getSmartLoadingOffset(curves);
            allY = [];
            hold(app.UIAxes_10,'on');
            for k = 1:numel(selected)
                baseline = (k-1)*offsetStep;
                y = curves(:,k)+baseline;
                yline(app.UIAxes_10,baseline,'--','HandleVisibility','off');
                if strcmp(app.ClassificationResult.Method,'LDA')
                    name = sprintf('LD%d',selected(k));
                else
                    name = sprintf('Weight %d',selected(k));
                end
                plot(app.UIAxes_10,wn,y,'LineWidth',1.4,'DisplayName',name);
                allY = [allY;y(:)]; %#ok<AGROW>
            end
            hold(app.UIAxes_10,'off');
            xlabel(app.UIAxes_10,'Raman shift (cm^{-1})');
            if strcmp(app.ClassificationResult.Method,'LDA')
                ylabel(app.UIAxes_10,'Loading + offset');
                title(app.UIAxes_10,'LDA loadings');
            else
                ylabel(app.UIAxes_10,'Linear weight + offset');
                title(app.UIAxes_10,'SVM feature weights');
            end
            app.UIAxes_10.Box = 'on';
            app.UIAxes_10.TickDir = 'out';
            xlim(app.UIAxes_10,[min(wn) max(wn)]);
            app.applyRobustYLimits(app.UIAxes_10,allY);
            legend(app.UIAxes_10,'show','Location','best','Box','off');
        end
        function updateAnalysisSummary(app)
            active = app.getActiveROIs();
            lines = {};
            lines{end+1} = sprintf('Active spectral ROIs: %d',numel(active));
            for k = 1:numel(active)
                lines{end+1} = sprintf('%s: %.2f - %.2f cm^-1',active(k).Label,active(k).StartWN,active(k).EndWN);
            end
            mask = app.getActiveSpectralMask();
            lines{end+1} = sprintf('Selected spectral variables: %d',nnz(mask));
            if numel(app.DefineRange) == 2 && all(isfinite(app.DefineRange))
                lines{end+1} = sprintf('Defined Raman domain: %.2f - %.2f cm^-1',app.DefineRange(1),app.DefineRange(2));
            end
            if ~isempty(app.PCAResult) && isstruct(app.PCAResult) && isfield(app.PCAResult,'Explained') && ~isempty(app.PCAResult.Explained)
                n = min(3,numel(app.PCAResult.Explained));
                for p = 1:n
                    lines{end+1} = sprintf('PC%d explained variance: %.2f %%',p,app.PCAResult.Explained(p));
                end
            end
            if ~isempty(app.ClassificationResult) && isstruct(app.ClassificationResult) && ~isempty(fieldnames(app.ClassificationResult))
                if isfield(app.ClassificationResult,'Method')
                    lines{end+1} = sprintf('Classification: %s',string(app.ClassificationResult.Method));
                end
                if isfield(app.ClassificationResult,'Accuracy') && ~isempty(app.ClassificationResult.Accuracy) && isfinite(app.ClassificationResult.Accuracy)
                    lines{end+1} = sprintf('Accuracy: %.2f %%',100*app.ClassificationResult.Accuracy);
                end
                if isfield(app.ClassificationResult,'Validation') && strlength(string(app.ClassificationResult.Validation)) > 0
                    lines{end+1} = sprintf('Validation: %s',string(app.ClassificationResult.Validation));
                end
            end
            app.TextArea.Value = lines;
        end
        function refreshAnalysisTree(app)
            delete(app.Tree.Children);
            dataNode = uitreenode(app.Tree,'Text','Dataset');
            if ~isempty(app.Spectra)
                uitreenode(dataNode,'Text',sprintf('%d spectra',size(app.Spectra,1)));
                uitreenode(dataNode,'Text',sprintf('%d Raman variables',size(app.Spectra,2)));
                if numel(app.DefineRange) == 2 && all(isfinite(app.DefineRange))
                    uitreenode(dataNode,'Text',sprintf('Defined range: %.1f - %.1f cm^-1',app.DefineRange(1),app.DefineRange(2)));
                end
            end
            roiRoot = uitreenode(app.Tree,'Text','Spectral ROIs');
            for k = 1:numel(app.SelectedROIs)
                R = app.SelectedROIs(k);
                if R.Active
                    state = 'ON';
                else
                    state = 'OFF';
                end
                n = uitreenode(roiRoot,'Text',sprintf('%s | %.1f-%.1f | %s',R.Label,R.StartWN,R.EndWN,state));
                n.NodeData = struct('Type','ROI','ID',R.ID);
            end
            pcaNode = uitreenode(app.Tree,'Text','PCA');
            if ~isempty(app.PCAResult) && isstruct(app.PCAResult) && ~isempty(fieldnames(app.PCAResult))
                uitreenode(pcaNode,'Text','Current PCA model');
                if isfield(app.PCAResult,'Explained') && ~isempty(app.PCAResult.Explained)
                    nPC = min(3,numel(app.PCAResult.Explained));
                    for p = 1:nPC
                        uitreenode(pcaNode,'Text',sprintf('PC%d: %.1f%%',p,app.PCAResult.Explained(p)));
                    end
                end
            end
            ratioNode = uitreenode(app.Tree,'Text','Ratios');
            if ~isempty(app.RatioResult) && isstruct(app.RatioResult) && isfield(app.RatioResult,'Names') && ~isempty(app.RatioResult.Names)
                for k = 1:numel(app.RatioResult.Names)
                    uitreenode(ratioNode,'Text',char(string(app.RatioResult.Names{k})));
                end
            end
            classNode = uitreenode(app.Tree,'Text','Classification');
            if ~isempty(app.ClassificationResult) && isstruct(app.ClassificationResult) && ~isempty(fieldnames(app.ClassificationResult))
                if isfield(app.ClassificationResult,'Method') && strlength(string(app.ClassificationResult.Method)) > 0
                    methodName = string(app.ClassificationResult.Method);
                    if isfield(app.ClassificationResult,'Accuracy') && ~isempty(app.ClassificationResult.Accuracy) && isfinite(app.ClassificationResult.Accuracy)
                        uitreenode(classNode,'Text',sprintf('%s | %.1f%%',methodName,100*app.ClassificationResult.Accuracy));
                    else
                        uitreenode(classNode,'Text',char(methodName));
                    end
                end
            end
            expand(app.Tree);
        end
        function updateContextText(app, txt)
            if ischar(txt) || isstring(txt)
                app.TextArea.Value = cellstr(splitlines(string(txt)));
            elseif iscell(txt)
                app.TextArea.Value = txt;
            end
        end
        function renderWorkingAxis(app)
            cla(app.UIAxes_8);
            if isempty(app.Spectra) || isempty(app.Wavenumber)
                return
            end
            displayMask = app.getDefinedDisplayMask();
            wnPlot = app.Wavenumber(displayMask);
            labels = app.GroupLabels;
            groups = unique(labels,'stable');
            modeName = string(app.MapmodesDropDown_2.Value);
            yAll = [];
            hold(app.UIAxes_8,'on');
            switch modeName
                case "All spectra"
                    for g = 1:numel(groups)
                        idxGroup = app.groupMask(labels,groups(g));
                        Xi = app.Spectra(idxGroup,displayMask);
                        for n = 1:size(Xi,1)
                            h = plot(app.UIAxes_8,wnPlot,Xi(n,:),'LineWidth',0.45);
                            h.HitTest = 'off';
                            h.PickableParts = 'none';
                        end
                        yAll = [yAll;Xi(:)]; %#ok<AGROW>
                    end
                otherwise
                    for g = 1:numel(groups)
                        idxGroup = app.groupMask(labels,groups(g));
                        Xi = app.Spectra(idxGroup,displayMask);
                        y = mean(Xi,1,'omitnan');
                        h = plot(app.UIAxes_8,wnPlot,y,'LineWidth',1.4,'DisplayName',char(string(groups(g))));
                        h.HitTest = 'off';
                        h.PickableParts = 'none';
                        yAll = [yAll;y(:)]; %#ok<AGROW>
                    end
            end
            hold(app.UIAxes_8,'off');
            xlabel(app.UIAxes_8,'Raman shift (cm^{-1})');
            ylabel(app.UIAxes_8,'Intensity (a.u.)');
            title(app.UIAxes_8,'Working spectra');
            app.applySpectralAxisStyle(app.UIAxes_8);
            app.applyRobustYLimits(app.UIAxes_8,yAll);
            app.redrawROIs();
        end
        function pinWorkingAxis(app)
            cla(app.UIAxes_7);
            if isempty(app.Spectra) || isempty(app.Wavenumber)
                return
            end
            displayMask = app.getDefinedDisplayMask();
            wnPlot = app.Wavenumber(displayMask);
            labels = app.GroupLabels;
            groups = unique(labels,'stable');
            yAll = [];
            hold(app.UIAxes_7,'on');
            for g = 1:numel(groups)
                idx = app.groupMask(labels,groups(g));
                Xi = app.Spectra(idx,displayMask);
                y = mean(Xi,1,'omitnan');
                plot(app.UIAxes_7,wnPlot,y,'LineWidth',1.4,'DisplayName',char(string(groups(g))));
                yAll = [yAll;y(:)]; %#ok<AGROW>
            end
            hold(app.UIAxes_7,'off');
            xlabel(app.UIAxes_7,'Raman shift (cm^{-1})');
            ylabel(app.UIAxes_7,'Intensity (a.u.)');
            title(app.UIAxes_7,'Pinned reference spectra');
            app.applySpectralAxisStyle(app.UIAxes_7);
            app.applyRobustYLimits(app.UIAxes_7,yAll);
            app.PinnedView.Type = 'Working spectra';
            app.PinnedView.ROIs = app.SelectedROIs;
        end
        function mask = getDefinedDisplayMask(app)
            mask = true(size(app.Wavenumber));
            if numel(app.DefineRange) == 2 && all(isfinite(app.DefineRange)) && app.DefineRange(2) > app.DefineRange(1)
                mask = app.Wavenumber >= app.DefineRange(1) & app.Wavenumber <= app.DefineRange(2);
            end
            if ~any(mask)
                mask = true(size(app.Wavenumber));
            end
        end
        function pinCurrentPCA(app)
            cla(app.UIAxes_7);
            if isempty(fieldnames(app.PCAResult))
                return
            end
            token = regexp(app.scorepairsDropDown.Value,'PC(\d+)\s+vs\s+PC(\d+)','tokens','once');
            if isempty(token)
                pc1 = 1;
                pc2 = min(2,size(app.PCAResult.Score,2));
            else
                pc1 = str2double(token{1});
                pc2 = str2double(token{2});
            end
            score = app.PCAResult.Score;
            labels = app.PCAResult.Labels;
            groups = unique(labels,'stable');
            hold(app.UIAxes_7,'on');
            for g = 1:numel(groups)
                idx = app.groupMask(labels,groups(g));
                scatter(app.UIAxes_7,score(idx,pc1),score(idx,pc2),24,'filled');
            end
            hold(app.UIAxes_7,'off');
            xlabel(app.UIAxes_7,sprintf('PC%d',pc1));
            ylabel(app.UIAxes_7,sprintf('PC%d',pc2));
            title(app.UIAxes_7,'Pinned PCA scores');
            box(app.UIAxes_7,'on');
            app.PinnedView.Type = 'PCA';
            app.PinnedView.ROIs = app.SelectedROIs;
        end
        
        function applySpectralAxisStyle(app,ax)
            ax.Box = 'on';
            ax.XGrid = 'off';
            ax.YGrid = 'off';
            ax.TickDir = 'out';
            ax.Layer = 'top';
            ax.FontSize = 10;
            if numel(app.DefineRange) == 2 && ...
                    all(isfinite(app.DefineRange)) && ...
                    app.DefineRange(2) > app.DefineRange(1)
                ax.XLimMode = 'manual';
                ax.XLim = app.DefineRange;
            end
        end
        function applyRobustYLimits(app,ax,y)
            y = double(y(:));
            y = y(isfinite(y));
            if isempty(y)
                return
            end
            if numel(y) >= 20
                lo = prctile(y,1);
                hi = prctile(y,99);
            else
                lo = min(y);
                hi = max(y);
            end
            if ~isfinite(lo) || ~isfinite(hi)
                return
            end
            if hi <= lo
                d = max(abs(lo)*0.1,1);
                lo = lo-d;
                hi = hi+d;
            end
            pad = app.AxisPaddingFraction*(hi-lo);
            ylim(ax,[lo-pad hi+pad]);
        end
        function applyRobustXYLimits(app,ax,x,y)
            x = double(x(:));
            y = double(y(:));
            valid = isfinite(x) & isfinite(y);
            x = x(valid);
            y = y(valid);
            if isempty(x)
                return
            end
            app.setRobustNumericLimit(ax,x,'x');
            app.setRobustNumericLimit(ax,y,'y');
        end
        function setRobustNumericLimit(app,ax,v,whichAxis)
            v = double(v(:));
            v = v(isfinite(v));
            if isempty(v)
                return
            end
            if numel(v) >= 20
                lo = prctile(v,1);
                hi = prctile(v,99);
                outside = v < lo | v > hi;
                if nnz(outside) <= max(2,round(0.02*numel(v)))
                    vUse = v(~outside);
                    if ~isempty(vUse)
                        lo = min(vUse);
                        hi = max(vUse);
                    end
                else
                    lo = min(v);
                    hi = max(v);
                end
            else
                lo = min(v);
                hi = max(v);
            end
            if hi <= lo
                d = max(abs(lo)*0.1,1);
                lo = lo-d;
                hi = hi+d;
            end
            pad = app.AxisPaddingFraction*(hi-lo);
            lim = [lo-pad hi+pad];
            if strcmpi(whichAxis,'x')
                xlim(ax,lim);
            else
                ylim(ax,lim);
            end
        end
        function yOffset = getSmartLoadingOffset(app,curves)
            curves = double(curves);
            vals = curves(isfinite(curves));
            if isempty(vals)
                yOffset = 1;
                return
            end
            span = prctile(vals,99)-prctile(vals,1);
            if ~isfinite(span) || span <= eps
                span = max(abs(vals));
            end
            if ~isfinite(span) || span <= eps
                span = 1;
            end
            yOffset = span*(1+app.LoadingOffsetFraction);
        end
        function openSupportApp(app,context)
            context = upper(string(context));
            payload = app.buildSupportPayload(context);
            childValid = false;
            if ~isempty(app.SupportApp)
                try
                    childValid = isvalid(app.SupportApp) && ...
                        ~isempty(app.SupportApp.UIFigure) && ...
                        isvalid(app.SupportApp.UIFigure);
                catch
                    childValid = false;
                end
            end
            if childValid
                app.SupportApp.receiveSupportContext(app,context,payload);
                app.SupportApp.UIFigure.Visible = 'on';
                try
                    app.SupportApp.UIFigure.WindowState = 'normal';
                catch
                end
                figure(app.SupportApp.UIFigure);
            else
                app.SupportApp = subapp;
                app.SupportApp.receiveSupportContext(app,context,payload);
            end
            app.SupportContext = context;
        end
        function payload = buildSupportPayload(app,context)
            payload = struct();
            payload.Context = context;
            payload.DefineRange = app.DefineRange;
            payload.ActiveROIID = app.ActiveROIID;
            payload.ROIs = app.getSupportROICopy();
            payload.GroupNames = app.GroupNames;
            payload.MapMode1 = string(app.MapmodesDropDown.Value);
            payload.MapMode2 = string(app.MapmodesDropDown_2.Value);
            payload.PCAMode = string(app.scorepairsDropDown.Value);
            payload.PCALoadings = app.SelectedPCALoadings;
            payload.RatioMode = string(app.modesDropDown.Value);
            payload.ClassMethod = string(app.MethodsDropDown.Value);
            payload.ClassScoreMode = string(app.scorepairsDropDown_2.Value);
            payload.ClassLoadings = app.SelectedClassLoadings;
            switch context
                case "PCA_SCORE"
                    if ~isempty(app.PCAResult) && isfield(app.PCAResult,'Score')
                        payload.ResultType = "PCA";
                        payload.ScorePair = string(app.scorepairsDropDown.Value);
                    end
                case "PCA_LOADING"
                    payload.ResultType = "PCA";
                    payload.SelectedLoadings = app.SelectedPCALoadings;
                case "RATIO"
                    payload.ResultType = "RATIO";
                    payload.RatioMode = string(app.modesDropDown.Value);
                case "MAIN_AXIS_1"
                    payload.ResultType = "REFERENCE";
                    payload.MapMode = string(app.MapmodesDropDown.Value);
                case "MAIN_AXIS_2"
                    payload.ResultType = "WORKING";
                    payload.MapMode = string(app.MapmodesDropDown_2.Value);
                case "CLASS_SCORE"
                    payload.ResultType = "CLASSIFICATION";
                    payload.Method = string(app.MethodsDropDown.Value);
                    payload.ScoreMode = string(app.scorepairsDropDown_2.Value);
                case "CLASS_LOADING"
                    payload.ResultType = "CLASSIFICATION";
                    payload.Method = string(app.MethodsDropDown.Value);
                    payload.SelectedLoadings = app.SelectedClassLoadings;
            end
        end
        function rois = getSupportROICopy(app)
            rois = app.SelectedROIs;
            for k = 1:numel(rois)
                rois(k).Patch = [];
            end
        end
        
        function applySupportScoreDot(app,context,value)
            dotSize = max(5,min(150,double(value)));
            switch context
                case "PCA_SCORE"
                    app.setScatterSizeOnAxis(app.UIAxes_3,dotSize);
                case "CLASS_SCORE"
                    app.setScatterSizeOnAxis(app.UIAxes_9,dotSize);
            end
        end
        function setScatterSizeOnAxis(app,ax,dotSize)
            h = findobj(ax,'Type','Scatter');
            for k = 1:numel(h)
                h(k).SizeData = dotSize;
            end
        end
        function applySupportColormap(app,context,value)
            mapName = char(string(value));
            switch context
                case "MAIN_AXIS_1"
                    try
                        colormap(app.UIAxes_7,mapName);
                    catch
                    end
                case "MAIN_AXIS_2"
                    try
                        colormap(app.UIAxes_8,mapName);
                    catch
                    end
                case "PCA_SCORE"
                    try
                        colormap(app.UIAxes_3,mapName);
                    catch
                    end
                case "PCA_LOADING"
                    try
                        colormap(app.UIAxes_2,mapName);
                    catch
                    end
                case "RATIO"
                    try
                        colormap(app.UIAxes_4,mapName);
                    catch
                    end
                case "CLASS_SCORE"
                    try
                        colormap(app.UIAxes_9,mapName);
                    catch
                    end
                case "CLASS_LOADING"
                    try
                        colormap(app.UIAxes_10,mapName);
                    catch
                    end
            end
        end
        function applySupportCompareMode(app,context)
            [names,axesList] = app.getAvailableComparisonViews();
            if numel(names) < 2
                uialert(app.UIFigure,'At least two populated plots are required.','Compare modes');
                return
            end
            [idx,ok] = listdlg( ...
                'PromptString','Select two or more plots to compare:', ...
                'SelectionMode','multiple', ...
                'ListString',cellstr(names), ...
                'ListSize',[340 300]);
            if ~ok || numel(idx) < 2
                return
            end
            selectedNames = names(idx);
            selectedAxes = axesList(idx);
            app.openComparisonFigure(selectedNames,selectedAxes);
        end
        function [names,axesList] = getAvailableComparisonViews(app)
            names = strings(0,1);
            axesList = {};
            candidates = { ...
                "PCA scores",app.UIAxes_3; ...
                "PCA loadings",app.UIAxes_2; ...
                "Ratio",app.UIAxes_4; ...
                "Main axis 1",app.UIAxes_7; ...
                "Main axis 2",app.UIAxes_8; ...
                "Classification scores",app.UIAxes_9; ...
                "Classification loadings / weights",app.UIAxes_10};
            for k = 1:size(candidates,1)
                ax = candidates{k,2};
                if app.axisHasDisplayContent(ax)
                    names(end+1,1) = candidates{k,1}; %#ok<AGROW>
                    axesList{end+1,1} = ax; %#ok<AGROW>
                end
            end
        end
        function tf = axisHasDisplayContent(app,ax)
            tf = false;
            try
                children = ax.Children;
                if isempty(children)
                    return
                end
                for k = 1:numel(children)
                    h = children(k);
                    if isa(h,'matlab.graphics.chart.primitive.Line') || ...
                            isa(h,'matlab.graphics.chart.primitive.Scatter') || ...
                            isa(h,'matlab.graphics.primitive.Image') || ...
                            isa(h,'matlab.graphics.chart.primitive.Surface') || ...
                            isa(h,'matlab.graphics.primitive.Patch')
                        tf = true;
                        return
                    end
                end
            catch
                tf = false;
            end
        end
        function openComparisonFigure(app,names,axesList)
            n = numel(axesList);
            nCol = ceil(sqrt(n));
            nRow = ceil(n/nCol);
            f = figure('Name','Raman analysis comparison','Color','w');
            t = tiledlayout(f,nRow,nCol,'TileSpacing','compact','Padding','compact');
            for k = 1:n
                target = nexttile(t);
                app.copyAnalysisAxis(axesList{k},target);
                title(target,names(k),'Interpreter','none');
            end
        end
        function copyAnalysisAxis(app,sourceAx,targetAx)
            children = sourceAx.Children;
            for k = numel(children):-1:1
                h = children(k);
                if isa(h,'images.roi.Rectangle')
                    continue
                end
                try
                    copyobj(h,targetAx);
                catch
                end
            end
            try
                targetAx.XLim = sourceAx.XLim;
                targetAx.YLim = sourceAx.YLim;
            catch
            end
            try
                xlabel(targetAx,sourceAx.XLabel.String);
                ylabel(targetAx,sourceAx.YLabel.String);
            catch
            end
            targetAx.Box = 'on';
            targetAx.TickDir = 'out';
        end
        function copyAxisToReference(app,sourceAx,titleText)
            cla(app.UIAxes_7);
            children = sourceAx.Children;
            for k = numel(children):-1:1
                try
                    copyobj(children(k),app.UIAxes_7);
                catch
                end
            end
            app.UIAxes_7.XLim = sourceAx.XLim;
            app.UIAxes_7.YLim = sourceAx.YLim;
            app.UIAxes_7.XLabel.String = sourceAx.XLabel.String;
            app.UIAxes_7.YLabel.String = sourceAx.YLabel.String;
            title(app.UIAxes_7,titleText);
            app.UIAxes_7.Box = 'on';
        end
        function applySupportSingleWindow(app,context)
            switch context
                case "PCA_SCORE"
                    app.openAxisFigure(app.UIAxes_3,'PCA scores');
                case "PCA_LOADING"
                    app.openAxisFigure(app.UIAxes_2,'PCA loadings');
                case "RATIO"
                    app.openAxisFigure(app.UIAxes_4,'Ratio');
                case "MAIN_AXIS_1"
                    app.openAxisFigure(app.UIAxes_7,'Main axis 1');
                case "MAIN_AXIS_2"
                    app.openAxisFigure(app.UIAxes_8,'Main axis 2');
                case "CLASS_SCORE"
                    app.openAxisFigure(app.UIAxes_9,'Classification scores');
                case "CLASS_LOADING"
                    app.openAxisFigure(app.UIAxes_10,'Classification loadings');
            end
        end
        function openAxisFigure(app,sourceAx,figureName)
            f = figure('Name',figureName,'Color','w');
            ax = axes(f);
            children = sourceAx.Children;
            for k = numel(children):-1:1
                try
                    copyobj(children(k),ax);
                catch
                end
            end
            ax.XLim = sourceAx.XLim;
            ax.YLim = sourceAx.YLim;
            xlabel(ax,sourceAx.XLabel.String);
            ylabel(ax,sourceAx.YLabel.String);
            title(ax,sourceAx.Title.String);
            box(ax,'on');
        end
        function applySupportEditablePlot(app,context)
            switch context
                case "PCA_SCORE"
                    app.openAxisFigure(app.UIAxes_3,'Editable PCA scores');
                case "PCA_LOADING"
                    app.openAxisFigure(app.UIAxes_2,'Editable PCA loadings');
                case "RATIO"
                    app.openAxisFigure(app.UIAxes_4,'Editable ratio');
                case "MAIN_AXIS_1"
                    app.openAxisFigure(app.UIAxes_7,'Editable Main axis 1');
                case "MAIN_AXIS_2"
                    app.openAxisFigure(app.UIAxes_8,'Editable Main axis 2');
                case "CLASS_SCORE"
                    app.openAxisFigure(app.UIAxes_9,'Editable classification scores');
                case "CLASS_LOADING"
                    app.openAxisFigure(app.UIAxes_10,'Editable classification loadings');
            end
        end
        function applySupportRatioSettings(app,context)
            if context ~= "RATIO"
                return
            end
            uialert(app.UIFigure,'Ratio settings will be connected to the ratio configuration module.','Ratio settings');
        end
        function applySupportClusterLocate(app,context)
            switch context
                case "PCA_SCORE"
                    app.ShowPCAClusterEllipses = ~app.ShowPCAClusterEllipses;
                    app.plotPCAScores();
                case "CLASS_SCORE"
                    app.ShowClassClusterEllipses = ~app.ShowClassClusterEllipses;
                    app.plotClassificationScores();
            end
        end
        function [allX,allY] = drawScoreGroupEllipses(app,ax,x,y,labels)
            allX = [];
            allY = [];
            groupNames = unique(labels,'stable');
            theta = linspace(0,2*pi,240);
            for g = 1:numel(groupNames)
                idx = app.groupMask(labels,groupNames(g));
                xg = double(x(idx));
                yg = double(y(idx));
                valid = isfinite(xg) & isfinite(yg);
                xg = xg(valid);
                yg = yg(valid);
                if numel(xg) < 3
                    continue
                end
                mu = [mean(xg),mean(yg)];
                C = cov([xg yg]);
                if any(~isfinite(C(:))) || rank(C) < 1
                    continue
                end
                [V,D] = eig(C);
                d = max(real(diag(D)),0);
                ellipse = mu(:) + 2.2 * V * diag(sqrt(d)) * [cos(theta);sin(theta)];
                ex = ellipse(1,:);
                ey = ellipse(2,:);
                plot(ax,ex,ey,'LineWidth',1.5,'HandleVisibility','off');
                allX = [allX;ex(:)]; %#ok<AGROW>
                allY = [allY;ey(:)]; %#ok<AGROW>
            end
        end
        function applyScoreLimitsWithEllipses(app,ax,x,y,ellipseX,ellipseY)
            allX = [double(x(:));double(ellipseX(:))];
            allY = [double(y(:));double(ellipseY(:))];
            validX = isfinite(allX);
            validY = isfinite(allY);
            allX = allX(validX);
            allY = allY(validY);
            if isempty(allX) || isempty(allY)
                return
            end
            xl = [min(allX) max(allX)];
            yl = [min(allY) max(allY)];
            xr = diff(xl);
            yr = diff(yl);
            if ~isfinite(xr) || xr <= 0
                xr = 1;
            end
            if ~isfinite(yr) || yr <= 0
                yr = 1;
            end
            xlim(ax,[xl(1)-0.10*xr xl(2)+0.10*xr]);
            ylim(ax,[yl(1)-0.10*yr yl(2)+0.10*yr]);
        end
        function commitAnalysisGrouping(app,definitions)
            originalLabels = string(app.OriginalGroupLabels(:));
            newLabels = strings(size(originalLabels));
            newLabels(:) = "";
            for g = 1:numel(definitions)
                sources = string(definitions(g).SourceGroups(:));
                mask = ismember(originalLabels,sources);
                newLabels(mask) = string(definitions(g).Name);
            end
            keep = strlength(newLabels) > 0;
            if nnz(keep) < 2
                uialert(app.UIFigure,'The selected grouping contains too few spectra.','Name groups');
                return
            end
            app.GroupLabels = newLabels;
            app.GroupNames = cellstr(string({definitions.Name}));
            app.GroupDefinition = definitions;
            app.recomputeAfterGroupingChange();
        end
        function resetAnalysisGrouping(app)
            app.GroupLabels = string(app.OriginalGroupLabels(:));
            app.GroupNames = app.OriginalGroupNames;
            originalNames = unique(app.GroupLabels,'stable');
            app.GroupDefinition = struct('Name',{},'SourceGroups',{});
            for g = 1:numel(originalNames)
                app.GroupDefinition(g).Name = char(originalNames(g));
                app.GroupDefinition(g).SourceGroups = originalNames(g);
            end
            app.recomputeAfterGroupingChange();
        end
        function recomputeAfterGroupingChange(app)
            app.ShowPCAClusterEllipses = false;
            app.ShowClassClusterEllipses = false;
            if isempty(app.SelectedROIs)
                app.renderWorkingAxis();
                app.pinWorkingAxis();
                app.refreshAnalysisTree();
                app.updateGroupingSummary();
                return
            end
            app.renderWorkingAxis();
            app.computeLivePCA();
            app.computeLiveRatios();
            app.computeLiveClassification();
            app.refreshAnalysisTree();
            app.updateGroupingSummary();
        end
        function updateGroupingSummary(app)
            lines = {};
            lines{end+1} = sprintf('Original groups: %d',numel(unique(app.OriginalGroupLabels)));
            lines{end+1} = sprintf('Current analysis groups: %d',numel(unique(app.GroupLabels(strlength(string(app.GroupLabels))>0))));
            lines{end+1} = '';
            lines{end+1} = 'Current grouping:';
            for g = 1:numel(app.GroupDefinition)
                src = strjoin(string(app.GroupDefinition(g).SourceGroups),', ');
                lines{end+1} = sprintf('%s = %s',app.GroupDefinition(g).Name,src);
            end
            app.TextArea.Value = lines;
        end
        function applySupportNameGroups(app,context)
            originalGroups = unique(string(app.OriginalGroupLabels),'stable');
            if numel(originalGroups) < 2
                uialert(app.UIFigure,'At least two original groups are required.','Name groups');
                return
            end
            choice = uiconfirm( ...
                app.UIFigure, ...
                sprintf('Current data contain %d original groups.\nCreate a new analytical grouping or reset to the original groups?',numel(originalGroups)), ...
                'Name groups', ...
                'Options',{'Create grouping','Reset original','Cancel'}, ...
                'DefaultOption',1, ...
                'CancelOption',3);
            switch choice
                case 'Reset original'
                    app.resetAnalysisGrouping();
                    return
                case 'Cancel'
                    return
            end
            answer = inputdlg({'Number of new analysis groups:'},'Create analysis groups',[1 42],{'2'});
            if isempty(answer)
                return
            end
            nNew = round(str2double(answer{1}));
            if ~isfinite(nNew) || nNew < 1 || nNew > numel(originalGroups)
                uialert(app.UIFigure,'Invalid number of groups.','Name groups');
                return
            end
            definitions = struct('Name',{},'SourceGroups',{});
            usedOriginal = strings(0,1);
            for g = 1:nNew
                nameAnswer = inputdlg({sprintf('Name for analysis group %d:',g)},'Group name',[1 42],{sprintf('Group %d',g)});
                if isempty(nameAnswer)
                    return
                end
                newName = strtrim(string(nameAnswer{1}));
                if newName == ""
                    uialert(app.UIFigure,'Group name cannot be empty.','Name groups');
                    return
                end
                available = originalGroups(~ismember(originalGroups,usedOriginal));
                if isempty(available)
                    uialert(app.UIFigure,'No original groups remain available.','Name groups');
                    return
                end
                [idx,ok] = listdlg( ...
                    'PromptString',sprintf('Select original groups belonging to "%s":',newName), ...
                    'SelectionMode','multiple', ...
                    'ListString',cellstr(available), ...
                    'ListSize',[300 260]);
                if ~ok || isempty(idx)
                    return
                end
                selected = available(idx);
                definitions(g).Name = char(newName);
                definitions(g).SourceGroups = selected(:);
                usedOriginal = [usedOriginal;selected(:)]; %#ok<AGROW>
            end
            unused = originalGroups(~ismember(originalGroups,usedOriginal));
            if ~isempty(unused)
                textUnused = strjoin(unused,', ');
                confirmUnused = uiconfirm( ...
                    app.UIFigure, ...
                    sprintf('These original groups are not assigned and will be excluded from current analyses:\n%s',textUnused), ...
                    'Unassigned groups', ...
                    'Options',{'Continue','Cancel'}, ...
                    'DefaultOption',1, ...
                    'CancelOption',2);
                if confirmUnused ~= "Continue"
                    return
                end
            end
            app.commitAnalysisGrouping(definitions);
        end
        function applySupportSpatialSettings(app,context)
            if context ~= "MAIN_AXIS_1" && context ~= "MAIN_AXIS_2"
                return
            end
            uialert(app.UIFigure,'Spatial settings are available when the current view contains spatial data.','Spatial settings');
        end
        function applySupportExcel(app,context)
            uialert(app.UIFigure,sprintf('Excel support requested from %s.',context),'Excel support');
        end
        
        function navigateSupportContext(app,currentContext,direction)
            contexts = app.SupportContextOrder;
            idx = find(contexts == upper(string(currentContext)),1,'first');
            if isempty(idx)
                idx = 1;
            end
            newIdx = idx + direction;
            newIdx = max(1,min(numel(contexts),newIdx));
            newContext = contexts(newIdx);
            payload = app.buildSupportPayload(newContext);
            app.SupportContext = newContext;
            if ~isempty(app.SupportApp)
                try
                    if isvalid(app.SupportApp)
                        app.SupportApp.receiveSupportContext(app,newContext,payload);
                    end
                catch
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: scorepairsDropDown
        function scorepairsDropDownValueChanged(app, event)
            app.plotPCAScores();
            
        end

        % Button down function: UIAxes_3
        function UIAxes_3ButtonDown(app, event)
            
        end

        % Button pushed function: iButton
        function iButtonPushed(app, event)
            app.openSupportApp("PCA_SCORE");
        end

        % Button pushed function: iButton_3
        function iButton_3Pushed(app, event)
            app.openSupportApp("PCA_LOADING");
        end

        % Value changed function: loadingsDropDown
        function loadingsDropDownValueChanged(app, event)
            value = string(app.loadingsDropDown.Value);
            if value == "Select multiple..."
                if isempty(app.PCAResult) || ~isfield(app.PCAResult,'Coeff')
                    return
                end
                nPC = min(size(app.PCAResult.Coeff,2),10);
                items = arrayfun(@(k)sprintf('PC%d',k),1:nPC,'UniformOutput',false);
                initial = app.SelectedPCALoadings;
                initial = initial(initial >= 1 & initial <= nPC);
                if isempty(initial)
                    initial = 1;
                end
                [idx,ok] = listdlg('PromptString','Select PCA loadings:','SelectionMode','multiple','ListString',items,'InitialValue',initial,'ListSize',[220 260]);
                if ok && ~isempty(idx)
                    app.SelectedPCALoadings = idx(:)';
                end
                app.loadingsDropDown.Value = sprintf('PC%d',app.SelectedPCALoadings(1));
            else
                token = regexp(value,'PC(\d+)','tokens','once');
                if ~isempty(token)
                    app.SelectedPCALoadings = str2double(token{1});
                end
            end
            app.plotPCALoadings();
            
        end

        % Button down function: UIAxes_2
        function UIAxes_2ButtonDown(app, event)
            
        end

        % Button pushed function: iButton_4
        function iButton_4Pushed(app, event)
            app.openSupportApp("RATIO");
        end

        % Value changed function: modesDropDown
        function modesDropDownValueChanged(app, event)
            app.plotSelectedRatio();
            
        end

        % Button down function: UIAxes_4
        function UIAxes_4ButtonDown(app, event)
            
        end

        % Value changed function: MapmodesDropDown
        function MapmodesDropDownValueChanged(app, event)
            value = app.MapmodesDropDown.Value;
            
        end

        % Button pushed function: iButton_5
        function iButton_5Pushed(app, event)
            app.openSupportApp("MAIN_AXIS_1");
        end

        % Button down function: UIAxes_7
        function UIAxes_7ButtonDown(app, event)
            
        end

        % Value changed function: MapmodesDropDown_2
        function MapmodesDropDown_2ValueChanged(app, event)
            app.renderWorkingAxis();
            
        end

        % Button pushed function: iButton_6
        function iButton_6Pushed(app, event)
            app.openSupportApp("MAIN_AXIS_2");
        end

        % Button down function: UIAxes_8
        function UIAxes_8ButtonDown(app, event)
            if isempty(app.Wavenumber)
                return
            end
            selection = string(app.UIFigure.SelectionType);
            if selection == "open"
                app.enterROINumerically();
                return
            end
            if selection == "normal"
                app.beginROIDraw();
            end
        end

        % Value changed function: MethodsDropDown
        function MethodsDropDownValueChanged(app, event)
            app.computeLiveClassification();
            app.updateAnalysisSummary();
            app.refreshAnalysisTree();
            
        end

        % Value changed function: scorepairsDropDown_2
        function scorepairsDropDown_2ValueChanged(app, event)
            app.plotClassificationScores();
            
        end

        % Button pushed function: iButton_7
        function iButton_7Pushed(app, event)
            app.openSupportApp("CLASS_SCORE");
        end

        % Value changed function: loadingsDropDown_2
        function loadingsDropDown_2ValueChanged(app, event)
            value = string(app.loadingsDropDown_2.Value);
            if value == "Select multiple..."
                if isempty(fieldnames(app.ClassificationResult)) || ~isfield(app.ClassificationResult,'Loadings')
                    return
                end
                n = size(app.ClassificationResult.Loadings,2);
                if strcmp(app.ClassificationResult.Method,'LDA')
                    items = arrayfun(@(k)sprintf('LD%d',k),1:n,'UniformOutput',false);
                else
                    items = arrayfun(@(k)sprintf('Weight %d',k),1:n,'UniformOutput',false);
                end
                initial = app.SelectedClassLoadings;
                initial = initial(initial >= 1 & initial <= n);
                if isempty(initial)
                    initial = 1;
                end
                [idx,ok] = listdlg('PromptString','Select loadings / weights:','SelectionMode','multiple','ListString',items,'InitialValue',initial,'ListSize',[240 260]);
                if ok && ~isempty(idx)
                    app.SelectedClassLoadings = idx(:)';
                end
                app.loadingsDropDown_2.Value = items{app.SelectedClassLoadings(1)};
            else
                token = regexp(value,'(\d+)','tokens','once');
                if ~isempty(token)
                    app.SelectedClassLoadings = str2double(token{1});
                end
            end
            app.plotClassificationLoadings();
            
        end

        % Button pushed function: iButton_8
        function iButton_8Pushed(app, event)
            app.openSupportApp("CLASS_LOADING");
        end

        % Button down function: UIAxes_10
        function UIAxes_10ButtonDown(app, event)
            
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            if isempty(selectedNodes)
                return
            end
            node = selectedNodes(1);
            data = node.NodeData;
            if isstruct(data) && isfield(data,'Type') && strcmp(data.Type,'ROI')
                app.ActiveROIID = data.ID;
                app.updateContextForActiveROI();
            end
            
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
            
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            selection = struct();
            selection.ROIs = app.SelectedROIs;
            for k = 1:numel(selection.ROIs)
                selection.ROIs(k).Patch = [];
            end
            selection.PCA = app.PCAResult;
            if isfield(selection.PCA,'Score')
                selection.PCA = rmfield(selection.PCA,'Score');
            end
            selection.Ratio = app.RatioResult;
            selection.Classification = app.ClassificationResult;
            if isfield(selection.Classification,'Model')
                selection.Classification = ...
                    rmfield(selection.Classification,'Model');
            end
            app.AnalysisPayload.AnalysisState = selection;
            parentApp = [];
            if isfield(app.AnalysisPayload,'ParentApp')
                parentApp = app.AnalysisPayload.ParentApp;
            end
            parentValid = false;
            if ~isempty(parentApp)
                try
                    parentValid = isvalid(parentApp) && ...
                        ~isempty(parentApp.UIFigure) && ...
                        isvalid(parentApp.UIFigure);
                catch
                    parentValid = false;
                end
            end
            if parentValid
                parentApp.UIFigure.Visible = 'on';
                try
                    parentApp.UIFigure.WindowState = 'normal';
                catch
                end
                figure(parentApp.UIFigure);
                app.UIFigure.Visible = 'off';
            else
                app.updateContextText( ...
                    'Analysis state stored. Parent Single mode is no longer available.');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.Mainaxis2Panel = uipanel(app.GridLayout3);
            app.Mainaxis2Panel.Title = 'Main axis 2';
            app.GridLayout9 = uigridlayout(app.Mainaxis2Panel);
            app.UIAxes_8 = uiaxes(app.GridLayout9);
            title(app.UIAxes_8, 'Axis')
            zlabel(app.UIAxes_8, 'Z')
            app.UIAxes_8.XTick = [];
            app.UIAxes_8.YTick = [];
            app.UIAxes_8.ButtonDownFcn = createCallbackFcn(app, @UIAxes_8ButtonDown, true);
            app.MapmodesDropDown_2Label = uilabel(app.GridLayout9);
            app.MapmodesDropDown_2Label.Text = 'Map modes';
            app.MapmodesDropDown_2 = uidropdown(app.GridLayout9);
            app.MapmodesDropDown_2.ValueChangedFcn = createCallbackFcn(app, @MapmodesDropDown_2ValueChanged, true);
            app.iButton_6 = uibutton(app.GridLayout9, 'push');
            app.iButton_6.ButtonPushedFcn = createCallbackFcn(app, @iButton_6Pushed, true);
            app.iButton_6.Text = 'i';
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.PCAPanel = uipanel(app.GridLayout);
            app.PCAPanel.Title = 'PCA';
            app.GridLayout6 = uigridlayout(app.PCAPanel);
            app.UIAxes_2 = uiaxes(app.GridLayout6);
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes_2ButtonDown, true);
            app.UIAxes_3 = uiaxes(app.GridLayout6);
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_3.ButtonDownFcn = createCallbackFcn(app, @UIAxes_3ButtonDown, true);
            app.scorepairsDropDownLabel = uilabel(app.GridLayout6);
            app.scorepairsDropDownLabel.Text = 'score pairs';
            app.scorepairsDropDown = uidropdown(app.GridLayout6);
            app.scorepairsDropDown.ValueChangedFcn = createCallbackFcn(app, @scorepairsDropDownValueChanged, true);
            app.loadingsDropDownLabel = uilabel(app.GridLayout6);
            app.loadingsDropDownLabel.Text = 'loadings';
            app.loadingsDropDown = uidropdown(app.GridLayout6);
            app.loadingsDropDown.ValueChangedFcn = createCallbackFcn(app, @loadingsDropDownValueChanged, true);
            app.iButton = uibutton(app.GridLayout6, 'push');
            app.iButton.ButtonPushedFcn = createCallbackFcn(app, @iButtonPushed, true);
            app.iButton.Text = 'i';
            app.iButton_3 = uibutton(app.GridLayout6, 'push');
            app.iButton_3.ButtonPushedFcn = createCallbackFcn(app, @iButton_3Pushed, true);
            app.iButton_3.Text = 'i';
            app.RatioPanel = uipanel(app.GridLayout);
            app.RatioPanel.Title = 'Ratio';
            app.GridLayout7 = uigridlayout(app.RatioPanel);
            app.UIAxes_4 = uiaxes(app.GridLayout7);
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.UIAxes_4.ButtonDownFcn = createCallbackFcn(app, @UIAxes_4ButtonDown, true);
            app.modesDropDownLabel = uilabel(app.GridLayout7);
            app.modesDropDownLabel.Text = 'modes';
            app.modesDropDown = uidropdown(app.GridLayout7);
            app.modesDropDown.ValueChangedFcn = createCallbackFcn(app, @modesDropDownValueChanged, true);
            app.iButton_4 = uibutton(app.GridLayout7, 'push');
            app.iButton_4.ButtonPushedFcn = createCallbackFcn(app, @iButton_4Pushed, true);
            app.iButton_4.Text = 'i';
            app.Mainaxis1Panel = uipanel(app.GridLayout);
            app.Mainaxis1Panel.Title = 'Main axis 1';
            app.GridLayout8 = uigridlayout(app.Mainaxis1Panel);
            app.UIAxes_7 = uiaxes(app.GridLayout8);
            title(app.UIAxes_7, 'Axis')
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_7.XTick = [];
            app.UIAxes_7.YTick = [];
            app.UIAxes_7.ButtonDownFcn = createCallbackFcn(app, @UIAxes_7ButtonDown, true);
            app.MapmodesDropDown = uidropdown(app.GridLayout8);
            app.MapmodesDropDown.ValueChangedFcn = createCallbackFcn(app, @MapmodesDropDownValueChanged, true);
            app.MapmodesDropDownLabel = uilabel(app.GridLayout8);
            app.MapmodesDropDownLabel.Text = 'Map modes';
            app.iButton_5 = uibutton(app.GridLayout8, 'push');
            app.iButton_5.ButtonPushedFcn = createCallbackFcn(app, @iButton_5Pushed, true);
            app.iButton_5.Text = 'i';
            app.ClassificationPanel = uipanel(app.GridLayout);
            app.ClassificationPanel.Title = 'Classification';
            app.GridLayout6_2 = uigridlayout(app.ClassificationPanel);
            app.UIAxes_9 = uiaxes(app.GridLayout6_2);
            title(app.UIAxes_9, 'Axis')
            zlabel(app.UIAxes_9, 'Z')
            app.UIAxes_9.XTick = [];
            app.UIAxes_9.YTick = [];
            app.UIAxes_10 = uiaxes(app.GridLayout6_2);
            title(app.UIAxes_10, 'Axis')
            zlabel(app.UIAxes_10, 'Z')
            app.UIAxes_10.XTick = [];
            app.UIAxes_10.YTick = [];
            app.UIAxes_10.ButtonDownFcn = createCallbackFcn(app, @UIAxes_10ButtonDown, true);
            app.loadingsDropDown_2Label = uilabel(app.GridLayout6_2);
            app.loadingsDropDown_2Label.Text = 'loadings';
            app.loadingsDropDown_2 = uidropdown(app.GridLayout6_2);
            app.loadingsDropDown_2.ValueChangedFcn = createCallbackFcn(app, @loadingsDropDown_2ValueChanged, true);
            app.MethodsDropDownLabel = uilabel(app.GridLayout6_2);
            app.MethodsDropDownLabel.Text = 'Methods';
            app.MethodsDropDown = uidropdown(app.GridLayout6_2);
            app.MethodsDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodsDropDownValueChanged, true);
            app.iButton_7 = uibutton(app.GridLayout6_2, 'push');
            app.iButton_7.ButtonPushedFcn = createCallbackFcn(app, @iButton_7Pushed, true);
            app.iButton_7.Text = 'i';
            app.scorepairsDropDown_2Label = uilabel(app.GridLayout6_2);
            app.scorepairsDropDown_2Label.Text = 'score pairs';
            app.scorepairsDropDown_2 = uidropdown(app.GridLayout6_2);
            app.scorepairsDropDown_2.ValueChangedFcn = createCallbackFcn(app, @scorepairsDropDown_2ValueChanged, true);
            app.iButton_8 = uibutton(app.GridLayout6_2, 'push');
            app.iButton_8.ButtonPushedFcn = createCallbackFcn(app, @iButton_8Pushed, true);
            app.iButton_8.Text = 'i';
            app.Tree = uitree(app.GridLayout);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Node = uitreenode(app.Tree);
            app.Node.Text = 'Node';
            app.Node2 = uitreenode(app.Node);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.Node);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.Node);
            app.Node4.Text = 'Node4';
            app.TextArea = uitextarea(app.GridLayout);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.FinishButton = uibutton(app.GridLayout, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = single_analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end