classdef single_extract < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        CellbackgroundsegmentationTab  matlab.ui.container.Tab
        GridLayout9                    matlab.ui.container.GridLayout
        ClusteringinteractionsPanel    matlab.ui.container.Panel
        GridLayout6                    matlab.ui.container.GridLayout
        FinishButton                   matlab.ui.control.Button
        SaveImagesButton               matlab.ui.control.Button
        RedoButton                     matlab.ui.control.Button
        UndoButton                     matlab.ui.control.Button
        DeleteButton                   matlab.ui.control.Button
        MergeButton                    matlab.ui.control.Button
        SplitButton                    matlab.ui.control.Button
        AssignButton                   matlab.ui.control.Button
        Tree                           matlab.ui.container.Tree
        Node                           matlab.ui.container.TreeNode
        Node2                          matlab.ui.container.TreeNode
        Node3                          matlab.ui.container.TreeNode
        Node4                          matlab.ui.container.TreeNode
        SegmentationsettingsPanel      matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        ClustersegmentationButton      matlab.ui.control.Button
        SegmethodDropDown              matlab.ui.control.DropDown
        SegmethodDropDownLabel         matlab.ui.control.Label
        MorphologyDropDown             matlab.ui.control.DropDown
        MorphologyDropDownLabel        matlab.ui.control.Label
        Panel_2                        matlab.ui.container.Panel
        GridLayout7                    matlab.ui.container.GridLayout
        MapcolorsDropDown              matlab.ui.control.DropDown
        MapcolorsDropDownLabel         matlab.ui.control.Label
        ClustercolorchangeButton       matlab.ui.control.Button
        ResetstateButton               matlab.ui.control.Button
        DisplayButton                  matlab.ui.control.Button
        DisplayDropDown                matlab.ui.control.DropDown
        ClusterlevelPanel              matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        PixellevelPanel                matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        UIAxes_4                       matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        GridLayout8                    matlab.ui.container.GridLayout
        ExtractselectedclustersButton  matlab.ui.control.Button
        TextArea                       matlab.ui.control.TextArea
        RunpixelaccessmentButton       matlab.ui.control.Button
        SinglespectraextractTab        matlab.ui.container.Tab
        GridLayout10                   matlab.ui.container.GridLayout
        TextArea_2                     matlab.ui.control.TextArea
        GridLayout14                   matlab.ui.container.GridLayout
        FinishButton_2                 matlab.ui.control.Button
        GridLayout13                   matlab.ui.container.GridLayout
        ListmodeDropDown               matlab.ui.control.DropDown
        ListmodeDropDownLabel          matlab.ui.control.Label
        ExtractsingledatasetPanel      matlab.ui.container.Panel
        GridLayout12                   matlab.ui.container.GridLayout
        GeneratesinglespectradatasetButton  matlab.ui.control.Button
        RuleDropDown                   matlab.ui.control.DropDown
        RuleDropDownLabel              matlab.ui.control.Label
        GridLayout11                   matlab.ui.container.GridLayout
        MaptypeDropDown                matlab.ui.control.DropDown
        MaptypeDropDownLabel           matlab.ui.control.Label
        LoadclustersButton             matlab.ui.control.Button
        Tree2                          matlab.ui.container.Tree
        Node_2                         matlab.ui.container.TreeNode
        Node2_2                        matlab.ui.container.TreeNode
        Node3_2                        matlab.ui.container.TreeNode
        Node4_2                        matlab.ui.container.TreeNode
        UIAxes2                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % =============================================================
        % PARENT APP
        % =============================================================
        
        mainApp
        
        % =============================================================
        % INPUT RAMAN DATA
        % =============================================================
        
        RamanData struct = struct()
        
        CurrentScanName char = ''
        CurrentScanID char = ''
        SourceMatPath char = ''
        OutputRoot char = ''
        
        RamanCube double = []
        Wavenumber double = []
        
        ValidMask2D logical = logical.empty
        ArtifactMask2D logical = logical.empty
        
        PreviewMap double = []
        PreviewLabel char = ''
        
        % =============================================================
        % CLUSTER WORKFLOW STATE
        % =============================================================
        
        ClusterReady logical = false
        ClusterConfirmed logical = false
        
        ClusterMethod char = 'PCA + K-means'
        ClusterMorphology char = 'Automatic / mixed'
        
        ClusterK double = 4
        ClusterVarianceThreshold double = 95
        ClusterMaxPC double = 15
        ClusterReplicates double = 10
        ClusterMaxIter double = 500
        ClusterRandomSeed double = 1
        
        ClusterTrainingMaxSpectra double = 20000
        
        % =============================================================
        % PCA / K-MEANS MODEL
        %
        % Row order of AllPCAScores corresponds to MetricPixelIdx.
        % =============================================================
        
        PCAcoeff double = []
        PCAmu double = []
        PCAExplained double = []
        NumPCUsed double = 0
        
        AllPCAScores double = []
        MetricPixelIdx double = []
        MetricLabels double = []
        
        ClusterCentroids double = []
        
        TrainingLocalIdx double = []
        TrainingLinearIdx double = []
        
        % =============================================================
        % CLUSTER MAP AND CLUSTER OBJECTS
        % =============================================================
        
        ClusterLabelMap double = []
        
        % Cluster fields:
        %   ID
        %   Name
        %   ParentID
        %   PixelIdx
        %   Class
        %   Active
        %   MeanSpectrum
        %   StdSpectrum
        %   Color
        %   Metrics
        Clusters struct = struct( ...
            'ID', {}, ...
            'Name', {}, ...
            'ParentID', {}, ...
            'PixelIdx', {}, ...
            'Class', {}, ...
            'Active', {}, ...
            'MeanSpectrum', {}, ...
            'StdSpectrum', {}, ...
            'Color', {}, ...
            'Metrics', {})
        
        NextClusterID double = 1
        SelectedClusterIDs double = []
        
        IsUpdatingTree logical = false
        
        % =============================================================
        % CLUSTER QC
        % =============================================================
        
        PixelConfidence double = []
        PixelConfidenceMap double = []
        
        GlobalClusterMetrics struct = struct()
        
        % =============================================================
        % MULTI-SEED K-MEANS STABILITY
        %
        % PCA is fitted once. Every stability run uses exactly the same:
        %   - training subset
        %   - PCA loading space
        %   - K
        %
        % For one fixed PC count, only the K-means seed changes.
        % =============================================================
        
        ClusterStabilityRuns double = 20
        
        ClusterRunResults struct = struct( ...
            'RunNumber', {}, ...
            'Seed', {}, ...
            'LabelsAll', {}, ...
            'LabelsTraining', {}, ...
            'Centroids', {}, ...
            'SumDistance', {}, ...
            'TotalSSE', {}, ...
            'MedianARI', {})
        
        ClusterPairwiseARI double = []
        
        ClusterSelectedRun double = NaN
        ClusterSelectedSeed double = NaN
        
        ClusterOverallMedianARI double = NaN
        ClusterMedoidMedianARI double = NaN
        
        ClusterStabilityLevel char = 'Not assessed'
        
        ClusterStabilityFigure = []
        
        % =============================================================
        % AUTOMATIC PCA-DIMENSION SELECTION
        %
        % PCA itself is fitted only once up to PCASelectionMaximumPC.
        %
        % The app evaluates several nested PCA score spaces:
        %
        %   PC1:PCCandidate(1)
        %   PC1:PCCandidate(2)
        %   ...
        %
        % For every candidate PC count:
        %   - run coarse multi-seed K-means;
        %   - calculate overall pairwise ARI;
        %   - select a medoid run;
        %   - calculate centroid-assignment confidence;
        %   - calculate map fragmentation.
        %
        % The best candidate is then validated using the full number of
        % stability runs.
        % =============================================================
        
        PCASelectionEnabled logical = true
        
        PCASelectionCandidateText char = ...
            '5 10 15 20 25 30 40 50 75 100'
        
        PCASelectionCandidates double = ...
            [5 10 15 20 25 30 40 50 75 100]
        
        PCASelectionMaximumPC double = 100
        
        % Fast preliminary screening.
        PCASelectionCoarseRuns double = 8
        
        % Number of top candidates that receive full validation.
        PCASelectionFinalCandidateCount double = 3
        
        % Final validation normally uses ClusterStabilityRuns.
        PCASelectionFinalRuns double = 20
        
        % Scientific selection rules.
        PCASelectionMinimumARI double = 0.90
        PCASelectionARITolerance double = 0.01
        PCASelectionConfidenceRetention double = 0.90
        
        % Optional spatial QC. It is not used as the primary optimization
        % target, but is reported to identify highly fragmented solutions.
        PCASelectionMaximumFragmentationIncrease double = 0.10
        
        PCASelectionResults struct = struct( ...
            'PCCount', {}, ...
            'ExplainedVariance', {}, ...
            'Stage', {}, ...
            'NumberRuns', {}, ...
            'OverallMedianARI', {}, ...
            'MedoidMedianARI', {}, ...
            'MedianConfidence', {}, ...
            'LowConfidenceFraction', {}, ...
            'MedianLargestComponentFraction', {}, ...
            'MedianIsolatedComponentFraction', {}, ...
            'TotalConnectedComponents', {}, ...
            'SelectedRun', {}, ...
            'SelectedSeed', {}, ...
            'MedoidLabelsAll', {}, ...
            'MedoidCentroids', {}, ...
            'NearestDistance', {}, ...
            'AssignmentConfidence', {}, ...
            'RunResults', {}, ...
            'PairwiseARI', {}, ...
            'StabilityLevel', {}, ...
            'Status', {}, ...
            'SelectionReason', {})
        
        PCASelectionSelectedIndex double = NaN
        PCASelectionSelectedPC double = NaN
        
        PCASelectionReferenceIndex double = NaN
        
        % ARI between medoid maps obtained at different candidate PC counts.
        PCASelectionMapAgreement double = []
        
        PCASelectionCompleted logical = false
        
        PCASelectionFigure = []
        
        
        % =============================================================
        % CLUSTER-SELECTION EVIDENCE STORAGE
        %
        % Every automatic PC-selection run is archived silently.
        % Review figures are written to disk but are not opened.
        % =============================================================
        
        ClusterEvidenceFolder char = ''
        ClusterEvidenceLatestFile char = ''
        
        ClusterEvidenceAutoSave logical = true
        ClusterEvidenceSaveFigures logical = true
        
        ClusterEvidenceVersion char = ...
            'single_extract_cluster_evidence_v1'
        % =============================================================
        % ADAPTIVE LOCAL SPLIT
        %
        % Split always uses the existing global PCA score space.
        % PCA is never refitted during an interactive split.
        %
        % Workflow:
        %   5 quick independent runs
        %       ARI >= 0.95  -> use quick medoid
        %       0.80-0.95    -> extend to 10 total runs
        %       ARI < 0.80   -> stop; split is not recommended
        %
        % After extension:
        %       ARI >= 0.90  -> split may be accepted
        %       ARI < 0.90   -> stop
        % =============================================================
        
        SplitQuickRuns double = 5
        SplitExtendedRuns double = 10
        SplitInternalReplicates double = 1
        
        SplitQuickAcceptARI double = 0.95
        SplitQuickRejectARI double = 0.80
        SplitFinalAcceptARI double = 0.90
        
        % Stop repeated hierarchical splitting.
        %
        % Initial cluster depth = 0
        % First-generation child depth = 1
        % Second-generation child depth = 2
        SplitMaximumDepth double = 2
        
        % Prevent tiny, likely outlier-driven children.
        SplitMinimumChildPixels double = 5
        SplitMinimumChildFraction double = 0.02
        
        % Median confidence based on nearest versus second-nearest
        % child centroid distance.
        SplitMinimumMedianConfidence double = 0.15
        
        LastSplitStability struct = struct()
        
        % =============================================================
        % RESET / FINAL WORKFLOW STATE
        % =============================================================
        
        InitialClusterSnapshot struct = struct()
        
        LastSavedImageFolder char = ''
        LastFinalSessionPath char = ''
        
        % =============================================================
        % HISTORY
        % =============================================================
        
        UndoStack cell = {}
        RedoStack cell = {}
        MaxHistory double = 30
        
        % =============================================================
        % DISPLAY
        % =============================================================
        
        ClusterColorMode char = 'Default'
        MaxDisplayedSpectra double = 300
        
        % =============================================================
        % PURITY RESULT DISPLAY
        %
        % Purity components are selectable spectral groups.
        % They do not replace or modify segmentation clusters.
        % =============================================================
        
        PurityBackgroundColor double = [0.15 0.45 0.95]
        PurityIntermediateColor double = [0.95 0.65 0.08]
        PurityCellColor double = [0.10 0.80 0.24]
        
        
        % =============================================================
        % CLUSTER DISPLAY AND DYNAMIC LAYERS
        % =============================================================
        
        ClusterOverlayAlpha double = 0.48
        
        ClusterClassLayers cell = { ...
            'Unassigned', ...
            'Cell candidate', ...
            'Background candidate', ...
            'Mixed / uncertain'}
        
        LastMapColorTarget char = 'Cluster map (Axis 1)'
        
        
        % =============================================================
        % PIXEL-LEVEL ASSESSMENT STATE
        % =============================================================
        
        PixelAssessmentReady logical = false
        PixelAssessmentConfirmed logical = false
        
        % User-drawn high-confidence ROI references.
        %
        % Fields:
        %   ID
        %   Class
        %   RawMask
        %   CleanMask
        %   OriginalPixelCount
        %   ErodedPixelCount
        %   UsablePixelCount
        %   OutlierFraction
        %   Status
        ReferenceROIs struct = struct( ...
            'ID', {}, ...
            'Class', {}, ...
            'RawMask', {}, ...
            'CleanMask', {}, ...
            'OriginalPixelCount', {}, ...
            'ErodedPixelCount', {}, ...
            'UsablePixelCount', {}, ...
            'OutlierFraction', {}, ...
            'Status', {})
        
        NextReferenceROIID double = 1
        
        BackgroundReferenceMask2D logical = logical.empty
        CellReferenceMask2D logical = logical.empty
        
        % Balanced model-training data.
        BalancedTrainingPixelIdx double = []
        TrainingLabels double = []
        TrainingWeights double = []
        TrainingROIIDs double = []
        
        % Pixel model and feature definition.
        PixelModel = []
        PixelModelType char = ''
        PixelFeatureNames cell = {}
        PixelFeatureMu double = []
        PixelFeatureSigma double = []
        
        % Pixel-level outputs.
        CellMembershipMap double = []
        PixelUncertaintyMap double = []
        PixelReliabilityMap double = []
        
        FinalCellMask2D logical = logical.empty
        FinalBackgroundMask2D logical = logical.empty
        FinalUncertainMask2D logical = logical.empty
        FinalClassMap double = []
        
        % Thresholds intentionally conservative for background removal.
        PixelBackgroundThreshold double = 0.15
        PixelCellThreshold double = 0.75
        
        % ROI QC and sampling settings.
        ReferenceErosionRadius double = 1
        ReferenceOutlierMADThreshold double = 4
        % Minimum clean pixels required for one ROI to contribute.
        MinUsablePixelsPerROI double = 5
        % Minimum pooled clean pixels required for each class.
        MinTotalUsablePixelsPerClass double = 30
        TargetPixelsPerROI double = 300
        MaxPixelsPerROI double = 500
        MaxPixelsPerClass double = 3000
        PixelModelMaxPC double = 10
        PixelModelRandomSeed double = 17
        
        PixelAssessmentMetrics struct = struct()
        % =============================================================
        % CLUSTER EVIDENCE INTERPRETATION
        %
        % Ground truth is treated as local reference evidence only.
        % These thresholds prevent overclaiming at whole-cluster level.
        % =============================================================
        
        ClusterGTEvidenceMinPixels double = 20
        ClusterGTEvidenceMinCoverage double = 0.05
        ClusterGTAgreementThreshold double = 0.90
        
        ClusterModelBackgroundSupport double = 0.85
        ClusterModelCellSupport double = 0.70
        ClusterOpposingFractionMax double = 0.05
        
        
        % =============================================================
        % REFERENCE-COMPONENT ASSESSMENT
        %
        % Results are stored independently by:
        %   ClusterID + ComponentName
        %
        % Reassessing the same cluster/component replaces only that
        % record. Results for other clusters/components are preserved.
        % =============================================================
        
        ComponentAssessments struct = struct( ...
            'ClusterID', {}, ...
            'ClusterName', {}, ...
            'ComponentName', {}, ...
            'ReferenceFolder', {}, ...
            'ReferenceFiles', {}, ...
            'ReferenceCount', {}, ...
            'RejectedFiles', {}, ...
            'ReferenceWavenumber', {}, ...
            'ReferenceSpectra', {}, ...
            'ConsensusReference', {}, ...
            'ReferenceVariability', {}, ...
            'ReferenceConsistencyMedian', {}, ...
            'ReferenceConsistencyMinimum', {}, ...
            'PixelIdx', {}, ...
            'MatchScores', {}, ...
            'MatchScoreMap', {}, ...
            'LowSupportMask', {}, ...
            'IntermediateSupportMask', {}, ...
            'HighSupportMask', {}, ...
            'LowThreshold', {}, ...
            'HighThreshold', {}, ...
            'ReferenceRatios750over785', {}, ...
            'ReferenceRatioMedian', {}, ...
            'ReferenceRatioP10', {}, ...
            'ReferenceRatioP90', {}, ...
            'PixelRatios750over785', {}, ...
            'RatioScoreValues', {}, ...
            'RatioScoreMap', {}, ...
            'RatioValidMask', {}, ...
            'RatioLowMask', {}, ...
            'RatioWeakMask', {}, ...
            'RatioModerateMask', {}, ...
            'RatioStrongMask', {}, ...
            'RatioWindows', {}, ...
            'Metrics', {}, ...
            'ClusterPixelSignature', {}, ...
            'CreatedAt', {}, ...
            'IsStale', {})
        
        CurrentComponentAssessmentIndex double = NaN
        
        ComponentAssessmentDefaultLowThreshold double = 0.60
        ComponentAssessmentDefaultHighThreshold double = 0.85
        
        ComponentAssessmentMinimumPoints double = 20
        
        
        % =============================================================
        % 750 / 785 RAMAN RATIO ASSESSMENT
        %
        % Peak intensities are integrated after local linear baseline
        % subtraction. Ratio compatibility is normalized to [0, 1].
        % =============================================================
        
        Ratio750Window double = [745 755]
        Ratio785Window double = [780 790]
        
        RatioBaselineLeftWindow double = [720 735]
        RatioBaselineRightWindow double = [800 815]
        
        % Score = exp(-abs(log(pixelRatio/referenceRatio))/tau)
        %
        % tau = log(2) means:
        %   ratio 2x or 0.5x the reference gives score approximately 0.5.
        RatioCompatibilityTau double = 0.693147180559945
        
        RatioScoreWeakThreshold double = 0.40
        RatioScoreModerateThreshold double = 0.60
        RatioScoreStrongThreshold double = 0.80
        
        
        % =============================================================
        % SINGLE-MODE HANDOFF
        % =============================================================
        
        SingleModeApp = []
        
        % =============================================================
        % EXTRACTION / REPRESENTATIVE SPECTRA
        %
        % Official balanced extraction levels:
        %   50 representatives per selected group
        %   40 representatives per selected group
        %   35 representatives per selected group
        %
        % Every selected group receives exactly the same number.
        % =============================================================
        
        RepresentativeMaximumCount double = 50
        RepresentativeMiddleCount double = 40
        RepresentativeMinimumCount double = 35
        
        % Recommended source-pixel contribution to one representative mean.
        RepresentativePreferredPixelsPerMean double = 5
        
        % Absolute minimum. Below two source pixels, the result is not
        % considered an averaged representative spectrum.
        RepresentativeMinimumPixelsPerMean double = 2
        
        ProcessedGroupPackageVersion char = ...
            'single_extract_processed_group_v1'
        
        ClusterPixelPackageVersion char = ...
            'single_extract_cluster_pixel_package_v1'
        
        RepresentativeExtractionVersion char = ...
            'single_extract_balanced_representatives_v1'


        % =============================================================
        % SINGLE SPECTRA DATASET BUILDER
        % =============================================================
        
        SingleExtractExtractionRoot char = ''
        
        SingleExtractReadableRoot char = ''
        
        SingleExtractOutputRoot char = ''
        
        SingleExtractBandRange double = ...
            [2800 2940]
        
        SingleExtractLoaded logical = false
        
        SingleExtractSelectedSampleIndex double = NaN
        
        % One structure per loaded scan/sample.
        %
        % Fields:
        %   Name
        %   ExtractionFolder
        %   RunFolder
        %   ClusterMATPath
        %   TracePNGPath
        %   ReadableMATPath
        %   ClusterPackage
        %   TraceImage
        %   BandMap
        %   ReadableWavenumber
        %   PixelCount
        SingleExtractSamples struct = struct( ...
            'Name', {}, ...
            'ExtractionFolder', {}, ...
            'RunFolder', {}, ...
            'ClusterMATPath', {}, ...
            'TracePNGPath', {}, ...
            'ReadableMATPath', {}, ...
            'ClusterPackage', {}, ...
            'TraceImage', {}, ...
            'BandMap', {}, ...
            'ReadableWavenumber', {}, ...
            'PixelCount', {})
        
        % Final group definition will be used later by Generate.
        SingleExtractGroups struct = struct( ...
            'Name', {}, ...
            'SampleIndices', {})
        
        SingleExtractRandomSeed double = 1701
        
        % Scientific minimum:
        % the smallest scan should support at least this many
        % representative spectra in Automatic mode.
        SingleExtractMinimumRepresentatives double = 35
        
        % =============================================================
        % DERIVED COMPONENT-LAYER AGGREGATES
        %
        % These are derived display/extraction layers only.
        % They are NOT segmentation clusters and are never written into
        % ClusterLabelMap.
        %
        % Key:
        %   LayerName + ComponentName
        % =============================================================
        
        ComponentLayerAggregates struct = struct( ...
            'LayerName', {}, ...
            'ComponentName', {}, ...
            'LayerClusterIDs', {}, ...
            'AssessedClusterIDs', {}, ...
            'AssessmentIndices', {}, ...
            'LayerMask', {}, ...
            'EvaluatedMask', {}, ...
            'UnassessedMask', {}, ...
            'WeakMask', {}, ...
            'MediumMask', {}, ...
            'StrongMask', {}, ...
            'LayerPixelCount', {}, ...
            'EvaluatedPixelCount', {}, ...
            'UnassessedPixelCount', {}, ...
            'WeakPixelCount', {}, ...
            'MediumPixelCount', {}, ...
            'StrongPixelCount', {}, ...
            'TotalClusterCount', {}, ...
            'AssessedClusterCount', {}, ...
            'CreatedAt', {})
        
        CurrentComponentLayerAggregateIndex double = NaN
        
        ComponentAggregateLayerColor double = [0.38 0.38 0.38]
        ComponentAggregateUnassessedColor double = [0.55 0.55 0.55]
        ComponentAggregateWeakColor double = [0.25 0.38 0.58]
        ComponentAggregateMediumColor double = [1.00 0.68 0.05]
        ComponentAggregateStrongColor double = [0.96 0.08 0.10]
        
        % =============================================================
        % SCAN-WISE REPRESENTATIVE EXTRACTION
        % =============================================================
        
        % Preferred lower bound used when Automatic mode determines
        % common pixel support across the loaded batch.
        SingleExtractPreferredMinimumPixelsPerMean double = 5
        
        % Absolute minimum for an actual multi-pixel mean.
        % A one-pixel isolated component is preserved, but explicitly
        % marked as Singleton rather than a normal averaged representative.
        SingleExtractMinimumPixelsPerMean double = 2
        
        % 8-neighbour connectivity is used for Raman-map spatial regions.
        SingleExtractSpatialConnectivity double = 8
        
        % Representative support is considered approximately on-target when
        % it falls within +/-25% of the requested target.
        % This is a QC descriptor only; it never deletes spectra.
        SingleExtractSupportToleranceFraction double = 0.25
        
        % Spectral heterogeneity QC is descriptive only.
        SingleExtractSpectralQCEnabled logical = true
        
        % Robust within-scan threshold:
        % median dispersion + 3 * robust MAD.
        SingleExtractSpectralQCMADMultiplier double = 3
        
        % Filled when Generate starts.
        SingleExtractCurrentTargetPixelsPerMean double = NaN
        
        % Current extraction implementation identifier.
        SingleExtractRepresentativeVersion char = ...
            'single_extract_scanwise_connected_v2'
        
        % Last generated scan-level results.
        SingleExtractLastResults struct = struct([])
        
        % Last generated file/output records.
        SingleExtractLastOutputInfo struct = struct([])
        
    end
    
    methods (Access = public)
        
        function setMainApp(app, mainAppIn)
            
            app.mainApp = mainAppIn;
            
        end
        
        
        function receiveScanForSegmentation(app, payload, mainAppIn)
            
            % =============================================================
            % RECEIVE A PRETREATED RAMAN SCAN FOR CELL-BACKGROUND SEGMENTATION
            %
            % Workflow:
            %
            %   scanspectra
            %       -> pretreatment / artifact handling completed
            %       -> single_extract
            %       -> Cell-background segmentation
            %
            % This is the LIVE-SCAN entry point.
            %
            % It is intentionally separate from:
            %   openSavedClusterExtractionMode()
            %
            % because saved-cluster mode starts from previously generated
            % segmentation / cluster packages.
            % =============================================================
            
            
            % =============================================================
            % 1. STORE PARENT APP
            % =============================================================
            
            if nargin >= 3 && ~isempty(mainAppIn)
                
                app.mainApp = ...
                    mainAppIn;
                
            end
            
            
            % =============================================================
            % 2. VALIDATE PAYLOAD
            % =============================================================
            
            if nargin < 2 || ...
                    isempty(payload) || ...
                    ~isstruct(payload)
                
                error( ...
                    'single_extract:InvalidScanPayload', ...
                    'Invalid scan payload received from scanspectra.');
                
            end
            
            
            requiredFields = { ...
                'Cube', ...
                'Wavenumber'};
            
            for k = 1:numel(requiredFields)
                
                fieldName = ...
                    requiredFields{k};
                
                if ~isfield(payload, fieldName) || ...
                        isempty(payload.(fieldName))
                    
                    error( ...
                        'single_extract:MissingPayloadField', ...
                        'Scan payload is missing field: %s', ...
                        fieldName);
                    
                end
                
            end
            
            
            % =============================================================
            % 3. RAMAN CUBE
            % =============================================================
            
            cube = ...
                double(payload.Cube);
            
            if ndims(cube) ~= 3
                
                error( ...
                    'single_extract:InvalidRamanCube', ...
                    'payload.Cube must be a 3-D Raman cube.');
                
            end
            
            [ny, nx, nWn] = ...
                size(cube);
            
            
            % =============================================================
            % 4. WAVENUMBER
            % =============================================================
            
            wn = ...
                double(payload.Wavenumber(:));
            
            if numel(wn) ~= nWn
                
                error( ...
                    'single_extract:WavenumberMismatch', ...
                    sprintf([ ...
                    'Raman cube and Wavenumber are not aligned.\n\n' ...
                    'Cube channels: %d\n' ...
                    'Wavenumber values: %d'], ...
                    nWn, ...
                    numel(wn)));
                
            end
            
            
            % =============================================================
            % 5. VALID PIXEL MASK
            %
            % IMPORTANT:
            % At this stage ValidMask means:
            %
            %   pixels that are analytically usable after pretreatment /
            %   artifact rejection
            %
            % It is NOT the final Cell mask.
            % =============================================================
            
            if isfield(payload, 'ValidMask2D') && ...
                    ~isempty(payload.ValidMask2D)
                
                validMask = ...
                    logical(payload.ValidMask2D);
                
            elseif isfield(payload, 'ValidMask') && ...
                    ~isempty(payload.ValidMask)
                
                validMask = ...
                    logical(payload.ValidMask);
                
            else
                
                validMask = ...
                    all(isfinite(cube), 3);
                
            end
            
            
            if ~isequal(size(validMask), [ny nx])
                
                error( ...
                    'single_extract:ValidMaskSizeMismatch', ...
                    sprintf([ ...
                    'ValidMask must match Raman cube spatial dimensions.\n\n' ...
                    'Expected: [%d %d]\n' ...
                    'Received: %s'], ...
                    ny, ...
                    nx, ...
                    mat2str(size(validMask))));
                
            end
            
            
            % =============================================================
            % 6. ARTIFACT MASK
            % =============================================================
            
            if isfield(payload, 'ArtifactMask2D') && ...
                    ~isempty(payload.ArtifactMask2D)
                
                artifactMask = ...
                    logical(payload.ArtifactMask2D);
                
            elseif isfield(payload, 'ArtifactMask') && ...
                    ~isempty(payload.ArtifactMask)
                
                artifactMask = ...
                    logical(payload.ArtifactMask);
                
            else
                
                artifactMask = ...
                    false(ny, nx);
                
            end
            
            
            if ~isequal(size(artifactMask), [ny nx])
                
                error( ...
                    'single_extract:ArtifactMaskSizeMismatch', ...
                    'Artifact mask must match Raman cube spatial dimensions.');
                
            end
            
            
            % =============================================================
            % 7. FINAL ANALYTICALLY VALID MASK
            %
            % Do not allow artifact or non-finite pixels into Cell-Background
            % assessment.
            % =============================================================
            
            validMask = ...
                validMask & ...
                ~artifactMask & ...
                all(isfinite(cube), 3);
            
            
            if ~any(validMask(:))
                
                error( ...
                    'single_extract:NoValidPixels', ...
                    ['No valid Raman pixels remain after applying ' ...
                    'the artifact / validity masks.']);
                
            end
            
            
            % =============================================================
            % 8. STORE CORE DATA
            % =============================================================
            
            app.RamanCube = ...
                cube;
            
            app.Wavenumber = ...
                wn;
            
            app.ValidMask2D = ...
                validMask;
            
            app.ArtifactMask2D = ...
                artifactMask;
            
            
            % =============================================================
            % 9. SCAN IDENTITY
            % =============================================================
            
            if isfield(payload, 'ScanName') && ...
                    ~isempty(payload.ScanName)
                
                app.CurrentScanName = ...
                    char(string(payload.ScanName));
                
            else
                
                app.CurrentScanName = ...
                    'Current scan';
                
            end
            
            
            % Keep source MAT information when available.
            if isfield(payload, 'SourceMatPath') && ...
                    ~isempty(payload.SourceMatPath)
                
                if isprop(app, 'SourceMatPath')
                    
                    app.SourceMatPath = ...
                        char(string(payload.SourceMatPath));
                    
                end
                
            end
            
            
            % =============================================================
            % 10. STORE COMPLETE LIVE-WORKFLOW METADATA
            % =============================================================
            
            app.RamanData = ...
                payload;
            
            
            % -------------------------------------------------------------
            % Scan ID
            % -------------------------------------------------------------
            
            if isfield(payload, 'ScanID') && ...
                    ~isempty(payload.ScanID)
                
                app.CurrentScanID = ...
                    char(string(payload.ScanID));
                
            else
                
                app.CurrentScanID = ...
                    app.makeSafeFileName(app.CurrentScanName);
                
            end
            
            
            % -------------------------------------------------------------
            % Output root for this live scan
            % -------------------------------------------------------------
            
            if isfield(payload, 'OutputRoot') && ...
                    ~isempty(payload.OutputRoot)
                
                app.OutputRoot = ...
                    char(string(payload.OutputRoot));
                
            else
                
                app.OutputRoot = ...
                    '';
                
            end
            
            
            % -------------------------------------------------------------
            % READABLE root
            % -------------------------------------------------------------
            
            if isfield(payload, 'ReadableRoot') && ...
                    ~isempty(payload.ReadableRoot)
                
                app.SingleExtractReadableRoot = ...
                    char(string(payload.ReadableRoot));
                
            else
                
                app.SingleExtractReadableRoot = ...
                    '';
                
            end
            
            
            % -------------------------------------------------------------
            % Final single-spectra output root
            % -------------------------------------------------------------
            
            if isfield(payload, 'SingleInputRoot') && ...
                    ~isempty(payload.SingleInputRoot)
                
                app.SingleExtractOutputRoot = ...
                    char(string(payload.SingleInputRoot));
                
            else
                
                app.SingleExtractOutputRoot = ...
                    '';
                
            end
            
            
            % -------------------------------------------------------------
            % Cluster-package root for CURRENT live scan
            %
            % payload.OutputRoot from scanspectra currently points to:
            %
            %   <workspace>\04_single_extract
            %
            % Therefore exported cluster packages will live below this root.
            % -------------------------------------------------------------
            
            if ~isempty(app.OutputRoot)
                
                app.SingleExtractExtractionRoot = ...
                    fullfile( ...
                    app.OutputRoot, ...
                    'extraction');
                
            else
                
                app.SingleExtractExtractionRoot = ...
                    '';
                
            end
            
            
            % =============================================================
            % 10. BUILD PREVIEW MAP
            % =============================================================
            
            [previewMap, previewLabel] = ...
                app.buildPreviewMap();
            
            app.PreviewMap = ...
                previewMap;
            
            if isprop(app, 'PreviewMapLabel')
                
                app.PreviewMapLabel = ...
                    previewLabel;
                
            end
            
            
            % =============================================================
            % 11. RESET OLD CELL-BACKGROUND RESULT
            %
            % New scan must never inherit ROI/model/masks from previous scan.
            % =============================================================
            
            app.resetPixelAssessmentState();
            
            
            % =============================================================
            % 12. RESET DOWNSTREAM CLUSTER RESULT
            % =============================================================
            
            app.resetClusterState();
            
            
            % =============================================================
            % 13. DISPLAY NEW INPUT
            % =============================================================
            
            app.displayInitialClusterInput();
            
            
            % =============================================================
            % 14. FORCE CELL-BACKGROUND TAB
            % =============================================================
            
            app.TabGroup.SelectedTab = ...
                app.CellbackgroundsegmentationTab;
            
            
            % =============================================================
            % 15. OPTIONAL QC TEXT
            % =============================================================
            
            try
                
                app.TextArea.Value = { ...
                    'QC control:'; ...
                    ''; ...
                    ['Scan: ' app.CurrentScanName]; ...
                    sprintf( ...
                    'Valid pixels: %d / %d', ...
                    nnz(app.ValidMask2D), ...
                    numel(app.ValidMask2D)); ...
                    sprintf( ...
                    'Excluded artifact pixels: %d', ...
                    nnz(app.ArtifactMask2D)); ...
                    ''; ...
                    'Next step: Run pixel assessment.'};
                
            catch
            end
            
            
            % =============================================================
            % 16. SHOW APP
            % =============================================================
            
            app.UIFigure.Visible = ...
                'on';
            
            drawnow;
            
        end


        function openSavedClusterExtractionMode(app, mainAppIn)
            
            % =============================================================
            % OPEN SAVED-CLUSTER EXTRACTION MODE
            %
            % OFFICIAL SAVED PACKAGE STRUCTURE:
            %
            % <ProjectRoot>\Data output\scan\single_extract\extraction\
            %     <SampleName>\
            %         cluster_pixel_packages\
            %             run_*\
            %                 *_cluster_ID_*.mat
            %
            % READABLE:
            %
            % <ProjectRoot>\Data input\scan\READABLE\
            %
            % SINGLE OUTPUT:
            %
            % <ProjectRoot>\Data input\single\
            %
            % No hard-coded drive.
            % No folder browser.
            % No mkdir.
            % =============================================================
            
            
            % =============================================================
            % 1. STORE PARENT
            % =============================================================
            
            if nargin < 2 || ...
                    isempty(mainAppIn) || ...
                    ~isvalid(mainAppIn)
                
                error( ...
                    'single_extract:InvalidParentApp', ...
                    'A valid scanspectra parent app is required.');
                
            end
            
            
            app.mainApp = ...
                mainAppIn;
            
            
            % =============================================================
            % 2. GET SESSION FROM SCANSPECTRA
            % =============================================================
            
            if ~ismethod( ...
                    app.mainApp, ...
                    'buildScanHomeSessionInfo')
                
                error( ...
                    'single_extract:MissingSessionMethod', ...
                    ['scanspectra does not provide ' ...
                    'buildScanHomeSessionInfo().']);
                
            end
            
            
            sessionInfo = ...
                app.mainApp.buildScanHomeSessionInfo();
            
            
            % =============================================================
            % 3. GET PROJECT ROOT
            % =============================================================
            
            if ~isfield(sessionInfo, 'ProjectRoot') || ...
                    isempty(sessionInfo.ProjectRoot)
                
                error( ...
                    'single_extract:ProjectRootMissing', ...
                    'ProjectRoot is missing from the scanspectra session.');
                
            end
            
            
            projectRoot = ...
                char(string(sessionInfo.ProjectRoot));
            
            
            % =============================================================
            % 4. RESOLVE EXACT STORAGE ROOTS
            % =============================================================
            
            extractionRoot = ...
                fullfile( ...
                projectRoot, ...
                'Data output', ...
                'scan', ...
                'single_extract', ...
                'extraction');
            
            
            readableRoot = ...
                fullfile( ...
                projectRoot, ...
                'Data input', ...
                'scan', ...
                'READABLE');
            
            
            singleOutputRoot = ...
                fullfile( ...
                projectRoot, ...
                'Data input', ...
                'single');
            
            
            % =============================================================
            % 5. VALIDATE EXISTING INPUT ROOTS
            % =============================================================
            
            if ~isfolder(extractionRoot)
                
                error( ...
                    'single_extract:ExtractionRootMissing', ...
                    ['Saved cluster extraction folder does not exist:' ...
                    newline newline ...
                    '%s'], ...
                    extractionRoot);
                
            end
            
            
            if ~isfolder(readableRoot)
                
                error( ...
                    'single_extract:ReadableRootMissing', ...
                    ['READABLE Raman folder does not exist:' ...
                    newline newline ...
                    '%s'], ...
                    readableRoot);
                
            end
            
            
            % =============================================================
            % 6. STORE ROOTS
            % =============================================================
            
            app.SingleExtractExtractionRoot = ...
                extractionRoot;
            
            
            app.SingleExtractReadableRoot = ...
                readableRoot;
            
            
            app.SingleExtractOutputRoot = ...
                singleOutputRoot;
            
            
            % =============================================================
            % 7. INITIALIZE TAB
            % =============================================================
            
            app.initializeSingleSpectraDatasetTab();
            
            
            app.TabGroup.SelectedTab = ...
                app.SinglespectraextractTab;
            
            
            % =============================================================
            % 8. REPORT
            % =============================================================
            
            app.TextArea_2.Value = { ...
                'SAVED-CLUSTER EXTRACTION MODE'; ...
                '================================================'; ...
                ''; ...
                ['Extraction root: ' extractionRoot]; ...
                ['Readable root: ' readableRoot]; ...
                ['Single output root: ' singleOutputRoot]; ...
                ''; ...
                'Press Load clusters.'};
            
            
            app.UIFigure.Visible = ...
                'on';
            
            
            drawnow;
            
        end
        
        
        function receiveSegmentationPayload(app, payload, mainAppIn)
            
            % =============================================================
            % RECEIVE CUBE FOR CLUSTER-LEVEL SEGMENTATION
            % =============================================================
            
            if nargin >= 3
                app.mainApp = mainAppIn;
            end
            
            if nargin < 2 || isempty(payload) || ~isstruct(payload)
                
                error( ...
                    'single_extract received an invalid segmentation payload.');
            end
            
            requiredFields = { ...
                'Cube', ...
                'Wavenumber'};
            
            for i = 1:numel(requiredFields)
                
                if ~isfield(payload, requiredFields{i})
                    
                    error( ...
                        'Segmentation payload is missing field: %s', ...
                        requiredFields{i});
                end
            end
            
            cube = double(payload.Cube);
            
            if isempty(cube) || ndims(cube) ~= 3
                
                error( ...
                    'Segmentation payload must contain a non-empty 3-D Raman cube.');
            end
            
            [ny, nx, nWn] = size(cube);
            
            wn = double(payload.Wavenumber(:));
            
            if numel(wn) ~= nWn
                
                error( ...
                    ['The number of wavenumber values does not match ' ...
                    'the spectral dimension of the Raman cube.']);
            end
            
            finiteMask = all(isfinite(cube), 3);
            
            % =============================================================
            % VALID MASK
            % =============================================================
            
            if isfield(payload, 'ValidMask') && ...
                    isequal(size(payload.ValidMask), [ny nx])
                
                validMask = logical(payload.ValidMask) & finiteMask;
                
            elseif isfield(payload, 'CellMask2D') && ...
                    isequal(size(payload.CellMask2D), [ny nx])
                
                validMask = logical(payload.CellMask2D) & finiteMask;
                
            else
                
                validMask = finiteMask;
            end
            
            % =============================================================
            % ARTIFACT MASK
            % =============================================================
            
            if isfield(payload, 'ArtifactMask2D') && ...
                    isequal(size(payload.ArtifactMask2D), [ny nx])
                
                artifactMask = logical(payload.ArtifactMask2D);
                
            else
                
                artifactMask = false(ny, nx);
            end
            
            validMask = validMask & ~artifactMask;
            
            if ~any(validMask(:))
                
                error( ...
                    'No valid Raman pixel remains for cluster segmentation.');
            end
            
            % =============================================================
            % STORE DATA
            % =============================================================
            
            app.RamanData = payload;
            
            app.RamanCube = cube;
            app.Wavenumber = wn;
            
            app.ValidMask2D = validMask;
            app.ArtifactMask2D = artifactMask;
            
            if isfield(payload, 'ScanName') && ...
                    ~isempty(payload.ScanName)
                
                app.CurrentScanName = ...
                    char(string(payload.ScanName));
                
            else
                
                app.CurrentScanName = ...
                    'Current Raman scan';
            end
            
            if isfield(payload, 'ScanID') && ...
                    ~isempty(payload.ScanID)
                
                app.CurrentScanID = ...
                    char(string(payload.ScanID));
                
            else
                
                app.CurrentScanID = ...
                    app.makeSafeFileName(app.CurrentScanName);
            end
            
            if isfield(payload, 'SourceMatPath')
                
                app.SourceMatPath = ...
                    char(string(payload.SourceMatPath));
                
            else
                
                app.SourceMatPath = '';
            end
            
            % =============================================================
            % WORKSPACE OUTPUT
            % =============================================================
            
            if isfield(payload, 'OutputRoot') && ...
                    ~isempty(payload.OutputRoot)
                
                app.OutputRoot = ...
                    char(string(payload.OutputRoot));
                
            else
                
                app.OutputRoot = '';
            end
            
            % =============================================================
            % SINGLE-EXTRACT STORAGE
            % =============================================================
            
            if isfield(payload, 'ReadableRoot') && ...
                    ~isempty(payload.ReadableRoot)
                
                app.SingleExtractReadableRoot = ...
                    char(string(payload.ReadableRoot));
                
            else
                
                app.SingleExtractReadableRoot = '';
            end
            
            if isfield(payload, 'SingleInputRoot') && ...
                    ~isempty(payload.SingleInputRoot)
                
                app.SingleExtractOutputRoot = ...
                    char(string(payload.SingleInputRoot));
                
            else
                
                app.SingleExtractOutputRoot = '';
            end
            
            if ~isempty(app.OutputRoot)
                
                app.SingleExtractExtractionRoot = ...
                    fullfile( ...
                    app.OutputRoot, ...
                    'extraction');
                
            else
                
                app.SingleExtractExtractionRoot = '';
            end
            
            % =============================================================
            % RESET CLUSTER STATE
            % =============================================================
            
            app.resetClusterState();
            
            [previewMap, previewLabel] = ...
                app.buildPreviewMap();
            
            app.PreviewMap = previewMap;
            app.PreviewLabel = previewLabel;
            
            app.displayInitialClusterInput();
            app.initializeSingleSpectraDatasetTab();
            
            app.TextArea.Value = { ...
                'CLUSTER SEGMENTATION READY'; ...
                '================================'; ...
                ''; ...
                ['Scan: ' app.CurrentScanName]; ...
                sprintf('Cube size: %d x %d x %d', ny, nx, nWn); ...
                sprintf('Valid pixels: %d / %d', ...
                nnz(app.ValidMask2D), ...
                numel(app.ValidMask2D)); ...
                sprintf('Artifact pixels excluded: %d', ...
                nnz(app.ArtifactMask2D)); ...
                ''; ...
                'Next step:'; ...
                'Choose morphology and segmentation method.'; ...
                'Then press Cluster segmentation.'};
            
            app.UIFigure.Visible = 'on';
            
        end
    end
    
    
    methods (Access = private)


        function initializeSingleExtract(app)
            
            % =============================================================
            % TREE
            % =============================================================
            
            try
                app.Tree.Multiselect = 'on';
            catch
            end
            
            try
                delete(app.Tree.Children);
            catch
            end
            
            % =============================================================
            % DROPDOWNS
            % =============================================================
            
            app.MorphologyDropDown.Items = { ...
                'Automatic / mixed', ...
                'Large isolated cells', ...
                'Dense small cells', ...
                'No morphology'};
            
            app.MorphologyDropDown.Value = ...
                'Automatic / mixed';
            
            
            app.SegmethodDropDown.Items = { ...
                'PCA + K-means', ...
                'Intensity threshold', ...
                'Manual only'};
            
            app.SegmethodDropDown.Value = ...
                'PCA + K-means';
            
            
            % =============================================================
            % DISPLAY MENU
            %
            % Only useful, non-duplicated views are retained.
            % =============================================================
            
            app.DisplayDropDown.Items = { ...
                'Cluster map', ...
                'Assigned class map', ...
                'Cluster confidence', ...
                'Cluster pixel count', ...
                'Selected spectra', ...
                'All active spectra', ...
                'Pixel assessment', ...
                'Cell mask', ...
                'Background mask', ...
                'Intermediate mask', ...
                'Reference ratio - continuous', ...
                'Reference ratio - 3 levels'};
            
            app.DisplayDropDown.Value = ...
                'Cluster map';
            
            % =============================================================
            % COLORMAP MENU
            % =============================================================
            
            app.MapcolorsDropDown.Items = { ...
                'Default', ...
                'Lines', ...
                'High contrast', ...
                'Soft', ...
                'Turbo', ...
                'Parula', ...
                'Jet', ...
                'HSV', ...
                'Hot'};
            
            app.MapcolorsDropDown.Value = ...
                'Default';
            
            % =============================================================
            % BUTTON TITLES
            % =============================================================
            
            app.ResetstateButton.Text = ...
                'Reset state';
            
            app.ExtractselectedclustersButton.Text = ...
                'Extract selected cluster data';
            
            app.SaveImagesButton.Text = ...
                'Save images';
            
            app.FinishButton.Text = ...
                'Finish';
            
            % =============================================================
            % TEXT AREA
            % =============================================================
            
            app.TextArea.Editable = 'off';
            
            % =============================================================
            % AXES
            % =============================================================
            
            app.resetImageAxis(app.UIAxes);
            title(app.UIAxes, 'Cluster map');
            
            app.resetSpectrumAxis(app.UIAxes_3);
            title(app.UIAxes_3, 'Cluster mean spectrum');
            
            % Leave Pixel-level axes empty.
            app.resetImageAxis(app.UIAxes_2);
            title(app.UIAxes_2, 'Pixel-level output');
            
            app.resetSpectrumAxis(app.UIAxes_4);
            title(app.UIAxes_4, 'Pixel-level spectral assessment');
            
            % =============================================================
            % EMPTY TREE
            % =============================================================
            
            root = uitreenode( ...
                app.Tree, ...
                'Text', ...
                'No cluster segmentation', ...
                'NodeData', ...
                struct('Type', 'root'));
            
            expand(root);
            
            % =============================================================
            % INITIAL CONTROL STATE
            % =============================================================
            
            app.setClusterControlsEnabled(false);
            
            app.ClustersegmentationButton.Enable = 'on';
            
        end


        function initializeSingleSpectraDatasetTab(app)
            
            % =============================================================
            % TREE
            % =============================================================
            
            try
                delete(app.Tree2.Children);
            catch
            end
            
            try
                app.Tree2.Multiselect = 'off';
            catch
            end
            
            root = ...
                uitreenode( ...
                app.Tree2, ...
                'Text', ...
                'No cluster package loaded', ...
                'NodeData', ...
                struct('Type', 'root'));
            
            expand(root);
            
            % =============================================================
            % LIST MODE
            % =============================================================
            
            app.ListmodeDropDown.Items = { ...
                'Loaded samples', ...
                'Pixel count'};
            
            app.ListmodeDropDown.Value = ...
                'Loaded samples';
            
            % =============================================================
            % MAP TYPE
            % =============================================================
            
            app.MaptypeDropDown.Items = { ...
                'Masked band', ...
                'Band', ...
                'Spectral'};
            
            app.MaptypeDropDown.Value = ...
                'Masked band';
            
            % =============================================================
            % EXTRACTION MODE
            %
            % Experimental grouping has deliberately been removed from
            % extraction. Every scan is processed and saved independently.
            % =============================================================
            
            app.RuleDropDownLabel.Text = ...
                'Mode';
            
            app.RuleDropDown.Items = { ...
                'Scan-wise extraction'};
            
            app.RuleDropDown.Value = ...
                'Scan-wise extraction';
            
            % There is currently only one scientifically supported mode.
            app.RuleDropDown.Enable = ...
                'off';
            
            % =============================================================
            % AXIS
            % =============================================================
            
            cla(app.UIAxes2, 'reset');
            
            app.UIAxes2.Box = ...
                'on';
            
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            
            title( ...
                app.UIAxes2, ...
                'Load cluster packages to begin');
            
            % =============================================================
            % REPORT AREA
            % =============================================================
            
            app.TextArea_2.Editable = ...
                'off';
            
            app.TextArea_2.Value = { ...
                'SCAN-WISE REPRESENTATIVE EXTRACTION'; ...
                '========================================'; ...
                ''; ...
                'No sample is loaded.'; ...
                ''; ...
                'Each loaded scan will be extracted independently.'; ...
                'Experimental grouping is performed later in single analysis.'; ...
                ''; ...
                'Press Load clusters.'};
            
            % =============================================================
            % RESET STATE
            % =============================================================
            
            app.SingleExtractLoaded = ...
                false;
            
            app.SingleExtractSelectedSampleIndex = ...
                NaN;
            
            app.SingleExtractSamples = ...
                app.SingleExtractSamples([]);
            
            % Legacy group state is intentionally left empty.
            app.SingleExtractGroups = ...
                app.SingleExtractGroups([]);
            
            app.SingleExtractLastResults = ...
                struct([]);
            
            app.SingleExtractLastOutputInfo = ...
                struct([]);
            
        end
        
        
        function clusterMATPath = findClusterPackageMAT(app, runFolder) %#ok<INUSD>
            
            clusterMATPath = '';
            
            matFiles = ...
                dir( ...
                fullfile( ...
                runFolder, ...
                '*.mat'));
            
            if isempty(matFiles)
                return
            end
            
            % Ignore unrelated MAT files if they ever appear later.
            candidateMask = ...
                startsWith( ...
                string({matFiles.name}), ...
                digitsPattern + "_cluster_ID_");
            
            candidates = ...
                matFiles(candidateMask);
            
            if isempty(candidates)
                
                % Compatibility fallback:
                % if there is exactly one MAT in the run, accept it.
                if numel(matFiles) == 1
                    
                    candidates = ...
                        matFiles;
                    
                else
                    
                    return
                end
            end
            
            if numel(candidates) ~= 1
                
                error( ...
                    'single_extract:UnexpectedClusterMATCount', ...
                    sprintf([ ...
                    'Run folder contains %d candidate cluster MAT files.\n\n' ...
                    'Expected exactly one cluster package:\n%s'], ...
                    numel(candidates), ...
                    runFolder));
            end
            
            clusterMATPath = ...
                fullfile( ...
                candidates(1).folder, ...
                candidates(1).name);
            
        end
        
        
        function runFolder = findLatestClusterPackageRun(app, sampleFolder) %#ok<INUSD>
            
            runFolder = '';
            
            packageRoot = ...
                fullfile( ...
                sampleFolder, ...
                'cluster_pixel_packages');
            
            if ~exist(packageRoot, 'dir')
                return
            end
            
            runEntries = ...
                dir( ...
                fullfile( ...
                packageRoot, ...
                'run_*'));
            
            runEntries = ...
                runEntries([runEntries.isdir]);
            
            if isempty(runEntries)
                return
            end
            
            % Sort according to run folder name.
            %
            % Current convention:
            % run_YYYYMMDD_HHMMSS_mmm
            %
            % Lexicographic order therefore equals chronological order.
            runNames = ...
                {runEntries.name};
            
            [~, order] = ...
                sort( ...
                lower(string(runNames)));
            
            latestEntry = ...
                runEntries(order(end));
            
            runFolder = ...
                fullfile( ...
                latestEntry.folder, ...
                latestEntry.name);
            
        end
        
        
        function resetClusterState(app)
            
            app.ClusterReady = false;
            app.ClusterConfirmed = false;
            
            app.PCAcoeff = [];
            app.PCAmu = [];
            app.PCAExplained = [];
            app.NumPCUsed = 0;
            
            app.AllPCAScores = [];
            app.MetricPixelIdx = [];
            app.MetricLabels = [];
            
            app.ClusterCentroids = [];
            
            app.TrainingLocalIdx = [];
            app.TrainingLinearIdx = [];
            
            app.ClusterLabelMap = [];
            
            app.Clusters = app.makeEmptyClusters();
            
            app.NextClusterID = 1;
            app.SelectedClusterIDs = [];
            
            app.PixelConfidence = [];
            app.PixelConfidenceMap = [];
            
            app.GlobalClusterMetrics = struct();
            % =============================================================
            % RESET MULTI-SEED STABILITY STATE
            % =============================================================
            
            % =============================================================
            % RESET MULTI-SEED STABILITY STATE
            % =============================================================
            
            app.ClusterRunResults = struct( ...
                'RunNumber', {}, ...
                'Seed', {}, ...
                'LabelsAll', {}, ...
                'LabelsTraining', {}, ...
                'Centroids', {}, ...
                'SumDistance', {}, ...
                'TotalSSE', {}, ...
                'MedianARI', {});
            
            app.ClusterPairwiseARI = [];
            
            app.ClusterSelectedRun = NaN;
            app.ClusterSelectedSeed = NaN;
            
            app.ClusterOverallMedianARI = NaN;
            app.ClusterMedoidMedianARI = NaN;
            
            app.ClusterStabilityLevel = ...
                'Not assessed';
            
            if ~isempty(app.ClusterStabilityFigure)
                
                try
                    
                    if isvalid(app.ClusterStabilityFigure)
                        delete(app.ClusterStabilityFigure);
                    end
                    
                catch
                end
            end
            
            app.ClusterStabilityFigure = [];
            
            % =============================================================
            % RESET AUTOMATIC PCA-SELECTION STATE
            % =============================================================
            
            app.PCASelectionResults = struct( ...
                'PCCount', {}, ...
                'ExplainedVariance', {}, ...
                'Stage', {}, ...
                'NumberRuns', {}, ...
                'OverallMedianARI', {}, ...
                'MedoidMedianARI', {}, ...
                'MedianConfidence', {}, ...
                'LowConfidenceFraction', {}, ...
                'MedianLargestComponentFraction', {}, ...
                'MedianIsolatedComponentFraction', {}, ...
                'TotalConnectedComponents', {}, ...
                'SelectedRun', {}, ...
                'SelectedSeed', {}, ...
                'MedoidLabelsAll', {}, ...
                'MedoidCentroids', {}, ...
                'NearestDistance', {}, ...
                'AssignmentConfidence', {}, ...
                'RunResults', {}, ...
                'PairwiseARI', {}, ...
                'StabilityLevel', {}, ...
                'Status', {}, ...
                'SelectionReason', {});
            
            app.PCASelectionSelectedIndex = NaN;
            app.PCASelectionSelectedPC = NaN;
            
            app.PCASelectionReferenceIndex = NaN;
            
            app.PCASelectionMapAgreement = [];
            
            app.PCASelectionCompleted = false;
            
            if ~isempty(app.PCASelectionFigure)
                
                try
                    
                    if isvalid(app.PCASelectionFigure)
                        delete(app.PCASelectionFigure);
                    end
                    
                catch
                end
            end
            
            app.PCASelectionFigure = [];
            app.ClusterEvidenceFolder = '';
            app.ClusterEvidenceLatestFile = '';
            app.LastSplitStability = ...
                struct();
            app.InitialClusterSnapshot = ...
                struct();
            
            app.LastSavedImageFolder = ...
                '';
            
            app.LastFinalSessionPath = ...
                '';
            
            % A new clustering state invalidates all previous
            % cluster-specific reference assessments.
            app.ComponentAssessments = ...
                app.makeEmptyComponentAssessments();
            
            app.CurrentComponentAssessmentIndex = ...
                NaN;
            
            % Derived layer aggregates are rebuilt from assessments.
            app.ComponentLayerAggregates = ...
                app.makeEmptyComponentLayerAggregates();
            
            app.CurrentComponentLayerAggregateIndex = ...
                NaN;
            
            app.UndoStack = {};
            app.RedoStack = {};
            
            
            try
                delete(app.Tree.Children);
            catch
            end
            
            app.setClusterControlsEnabled(false);
            app.resetPixelAssessmentState();
            
        end
        
        
        function clusters = makeEmptyClusters(app) %#ok<MANU>
            
            clusters = struct( ...
                'ID', {}, ...
                'Name', {}, ...
                'ParentID', {}, ...
                'PixelIdx', {}, ...
                'Class', {}, ...
                'Active', {}, ...
                'MeanSpectrum', {}, ...
                'StdSpectrum', {}, ...
                'Color', {}, ...
                'Metrics', {});
            
        end
        
        
        function setClusterControlsEnabled(app, enabled)
            
            % =============================================================
            % ENABLE OR DISABLE EXISTING CLUSTER CONTROLS
            %
            % Only real App Designer component properties are listed here.
            % =============================================================
            
            if enabled
                
                enableState = ...
                    'on';
                
            else
                
                enableState = ...
                    'off';
            end
            
            controls = { ...
                app.AssignButton, ...
                app.SplitButton, ...
                app.MergeButton, ...
                app.DeleteButton, ...
                app.UndoButton, ...
                app.RedoButton, ...
                app.SaveImagesButton, ...
                app.FinishButton, ...
                app.DisplayButton, ...
                app.DisplayDropDown, ...
                app.MapcolorsDropDown, ...
                app.ClustercolorchangeButton, ...
                app.ResetstateButton, ...
                app.RunpixelaccessmentButton, ...
                app.ExtractselectedclustersButton};
            
            for controlIndex = 1:numel(controls)
                
                currentControl = ...
                    controls{controlIndex};
                
                try
                    
                    if ~isempty(currentControl) && ...
                            isvalid(currentControl)
                        
                        currentControl.Enable = ...
                            enableState;
                    end
                    
                catch
                    % Ignore only an unavailable optional control.
                end
            end
            
            % Cluster segmentation remains available whenever Raman data exist.
            if ~isempty(app.RamanCube)
                
                app.ClustersegmentationButton.Enable = ...
                    'on';
                
            else
                
                app.ClustersegmentationButton.Enable = ...
                    'off';
            end
            
        end


        function [mapImg, mapLabel] = buildPreviewMap(app)
            
            cube = double(app.RamanCube);
            wn = double(app.Wavenumber(:));
            
            useBand = ...
                wn >= 2840 & ...
                wn <= 2940;
            
            if nnz(useBand) >= 3
                
                mapImg = ...
                    mean(cube(:, :, useBand), 3, 'omitnan');
                
                mapLabel = ...
                    'Mean Raman intensity 2840-2940 cm^{-1}';
                
            else
                
                mapImg = ...
                    mean(cube, 3, 'omitnan');
                
                mapLabel = ...
                    'Mean Raman intensity projection';
            end
            
            mapImg = double(mapImg);
            
            mapImg(~app.ValidMask2D) = NaN;
            
        end
        
        
        function resetSelectedWorkflowState(app)
            
            options = { ...
                'Display only', ...
                'Pixel purity assessment', ...
                'Reference-component assessments', ...
                'Cluster edits', ...
                'All results after scan loading', ...
                'Cancel'};
            
            choice = ...
                uiconfirm( ...
                app.UIFigure, ...
                { ...
                'Choose the state to reset.'; ...
                ''; ...
                ['Raw RamanCube, Wavenumber, ValidMask and artifact mask ' ...
                'are never modified by these reset operations.']}, ...
                'Reset state', ...
                'Options', ...
                options, ...
                'DefaultOption', ...
                'Display only', ...
                'CancelOption', ...
                'Cancel');
            
            switch choice
                
                case 'Display only'
                    
                    app.SelectedClusterIDs = [];
                    
                    try
                        app.Tree.SelectedNodes = [];
                    catch
                    end
                    
                    if app.ClusterReady
                        
                        app.DisplayDropDown.Value = ...
                            'Cluster map';
                        
                        app.displayClusterMap();
                        app.displayAllClusterMeans();
                        
                        if app.PixelAssessmentReady
                            app.displayPixelAssessmentResults();
                        else
                            app.resetImageAxis(app.UIAxes_2);
                            title(app.UIAxes_2, 'Pixel-level output');
                            
                            app.resetImageAxis(app.UIAxes_4);
                            title(app.UIAxes_4, ...
                                'Reference-component assessment');
                        end
                        
                        app.updateClusterInformationText();
                        
                    else
                        
                        app.displayInitialClusterInput();
                    end
                    
                case 'Pixel purity assessment'
                    
                    if ~app.PixelAssessmentReady && ...
                            isempty(app.ReferenceROIs)
                        
                        uialert( ...
                            app.UIFigure, ...
                            'No pixel purity state exists.', ...
                            'Nothing to reset');
                        
                        return
                    end
                    
                    confirmChoice = ...
                        uiconfirm( ...
                        app.UIFigure, ...
                        { ...
                        'Reset the complete pixel purity assessment?'; ...
                        ''; ...
                        'This removes:'; ...
                        '• reference ROIs'; ...
                        '• trained pixel model'; ...
                        '• P(cell), reliability and uncertainty maps'; ...
                        '• Cell / Background / Intermediate masks'; ...
                        '• purity nodes from the Tree'; ...
                        ''; ...
                        'Segmentation clusters will remain unchanged.'}, ...
                        'Reset pixel purity', ...
                        'Options', ...
                        {'Reset purity', 'Cancel'}, ...
                        'DefaultOption', ...
                        'Cancel', ...
                        'CancelOption', ...
                        'Cancel');
                    
                    if ~strcmp(confirmChoice, 'Reset purity')
                        return
                    end
                    
                    app.resetPixelAssessmentState();
                    
                    app.computeAllClusterMetrics([]);
                    
                    app.populateClusterTree();
                    app.displayClusterMap();
                    app.displayAllClusterMeans();
                    
                case 'Reference-component assessments'
                    
                    if isempty(app.ComponentAssessments)
                        
                        uialert( ...
                            app.UIFigure, ...
                            'No reference-component assessment exists.', ...
                            'Nothing to reset');
                        
                        return
                    end
                    
                    confirmChoice = ...
                        uiconfirm( ...
                        app.UIFigure, ...
                        { ...
                        'Remove all reference-component assessments?'; ...
                        ''; ...
                        ['Strong / Medium / Weak component results and ' ...
                        'derived aggregate nodes will be removed.']; ...
                        'Clusters and pixel purity will remain unchanged.'}, ...
                        'Reset component assessments', ...
                        'Options', ...
                        {'Reset components', 'Cancel'}, ...
                        'DefaultOption', ...
                        'Cancel', ...
                        'CancelOption', ...
                        'Cancel');
                    
                    if ~strcmp(confirmChoice, 'Reset components')
                        return
                    end
                    
                    app.ComponentAssessments = ...
                        app.makeEmptyComponentAssessments();
                    
                    app.ComponentLayerAggregates = ...
                        app.makeEmptyComponentLayerAggregates();
                    
                    app.CurrentComponentAssessmentIndex = ...
                        NaN;
                    
                    app.CurrentComponentLayerAggregateIndex = ...
                        NaN;
                    
                    app.populateClusterTree();
                    
                    app.resetImageAxis(app.UIAxes_4);
                    
                    title( ...
                        app.UIAxes_4, ...
                        'Reference-component assessment');
                    
                case 'Cluster edits'
                    
                    if isempty(fieldnames(app.InitialClusterSnapshot))
                        
                        uialert( ...
                            app.UIFigure, ...
                            ['No initial segmentation snapshot is available. ' ...
                            'Run Cluster segmentation first.'], ...
                            'No reset snapshot');
                        
                        return
                    end
                    
                    confirmChoice = ...
                        uiconfirm( ...
                        app.UIFigure, ...
                        { ...
                        'Restore the initial automatic segmentation?'; ...
                        ''; ...
                        'All later Split / Merge / Delete / Assign operations'; ...
                        'will be removed.'; ...
                        ''; ...
                        ['Pixel purity and component assessments will also be ' ...
                        'reset because they depend on the edited clusters.']}, ...
                        'Reset cluster edits', ...
                        'Options', ...
                        {'Restore initial clusters', 'Cancel'}, ...
                        'DefaultOption', ...
                        'Cancel', ...
                        'CancelOption', ...
                        'Cancel');
                    
                    if ~strcmp(confirmChoice, ...
                            'Restore initial clusters')
                        
                        return
                    end
                    
                    initialSnapshot = ...
                        app.InitialClusterSnapshot;
                    
                    app.resetPixelAssessmentState();
                    
                    app.ComponentAssessments = ...
                        app.makeEmptyComponentAssessments();
                    
                    app.ComponentLayerAggregates = ...
                        app.makeEmptyComponentLayerAggregates();
                    
                    app.restoreClusterSnapshot( ...
                        initialSnapshot);
                    
                    app.UndoStack = {};
                    app.RedoStack = {};
                    
                case 'All results after scan loading'
                    
                    confirmChoice = ...
                        uiconfirm( ...
                        app.UIFigure, ...
                        { ...
                        'Reset all segmentation and analysis results?'; ...
                        ''; ...
                        'The loaded Raman cube will remain available.'; ...
                        'Cluster segmentation must be run again.'}, ...
                        'Reset all results', ...
                        'Options', ...
                        {'Reset all', 'Cancel'}, ...
                        'DefaultOption', ...
                        'Cancel', ...
                        'CancelOption', ...
                        'Cancel');
                    
                    if ~strcmp(confirmChoice, 'Reset all')
                        return
                    end
                    
                    app.resetClusterState();
                    
                    app.displayInitialClusterInput();
                    
                    app.TextArea.Value = { ...
                        'WORKFLOW RESULTS RESET'; ...
                        '================================================'; ...
                        ''; ...
                        'The loaded Raman cube was preserved.'; ...
                        'Run Cluster segmentation to begin again.'};
                    
                otherwise
                    
                    return
            end
            
        end


        function applyNamedColormapToAxis( ...
                app, ...
                targetAxis, ...
                paletteName) %#ok<INUSD>
            
            if isempty(targetAxis) || ...
                    ~isvalid(targetAxis)
                
                return
            end
            
            switch lower(strtrim(paletteName))
                
                case lower('Lines')
                    
                    selectedMap = ...
                        lines(256);
                    
                case lower('High contrast')
                    
                    selectedMap = ...
                        turbo(256);
                    
                case lower('Soft')
                    
                    selectedMap = ...
                        parula(256);
                    
                    selectedMap = ...
                        0.82 .* selectedMap + 0.18;
                    
                case lower('Turbo')
                    
                    selectedMap = ...
                        turbo(256);
                    
                case lower('Parula')
                    
                    selectedMap = ...
                        parula(256);
                    
                case lower('Jet')
                    
                    selectedMap = ...
                        jet(256);
                    
                case lower('HSV')
                    
                    selectedMap = ...
                        hsv(256);
                    
                case lower('Hot')
                    
                    selectedMap = ...
                        hot(256);
                    
                otherwise
                    
                    selectedMap = ...
                        parula(256);
            end
            
            colormap( ...
                targetAxis, ...
                selectedMap);
            
        end
        
        function displayInitialClusterInput(app)
            
            app.resetImageAxis(app.UIAxes);
            
            imagesc(app.UIAxes, app.PreviewMap);
            
            axis(app.UIAxes, 'image');
            app.UIAxes.YDir = 'normal';
            
            colormap(app.UIAxes, turbo(256));
            colorbar(app.UIAxes);
            
            vals = app.PreviewMap( ...
                app.ValidMask2D & ...
                isfinite(app.PreviewMap));
            
            if ~isempty(vals)
                
                lo = prctile(vals, 2);
                hi = prctile(vals, 98);
                
                if isfinite(lo) && isfinite(hi) && lo < hi
                    clim(app.UIAxes, [lo hi]);
                end
            end
            
            title( ...
                app.UIAxes, ...
                { ...
                app.CurrentScanName, ...
                app.PreviewLabel, ...
                'Input map before clustering'}, ...
                'Interpreter', ...
                'none');
            
            app.resetSpectrumAxis(app.UIAxes_3);
            
            title( ...
                app.UIAxes_3, ...
                'Cluster mean spectrum will appear here');
            
        end
        
        
        function resetImageAxis(app, ax) %#ok<MANU>
            
            cla(ax, 'reset');
            
            ax.Box = 'on';
            ax.XGrid = 'off';
            ax.YGrid = 'off';
            
            ax.YDir = 'normal';
            ax.TickDir = 'in';
            ax.Layer = 'top';
            
            ax.FontSize = 10;
            
        end
        
        
        function ratioScore = calculateRatioCompatibilityScore( ...
                app, ...
                pixelRatio, ...
                referenceRatio)
            
            % =============================================================
            % SYMMETRIC RATIO COMPATIBILITY SCORE
            %
            % score = 1 when pixelRatio == referenceRatio.
            %
            % Ratios above and below the reference are penalized
            % symmetrically in logarithmic space.
            %
            % With tau = log(2):
            %   pixel/reference = 1.0  -> score = 1.00
            %   pixel/reference = 2.0  -> score = 0.37
            %   pixel/reference = 0.5  -> score = 0.37
            % =============================================================
            
            pixelRatio = ...
                double(pixelRatio);
            
            referenceRatio = ...
                double(referenceRatio);
            
            ratioScore = ...
                nan(size(pixelRatio));
            
            if ~isfinite(referenceRatio) || ...
                    referenceRatio <= 0
                
                return
            end
            
            validMask = ...
                isfinite(pixelRatio) & ...
                pixelRatio > 0;
            
            if ~any(validMask)
                return
            end
            
            logDifference = ...
                abs( ...
                log( ...
                pixelRatio(validMask) ./ ...
                referenceRatio));
            
            tau = ...
                max( ...
                app.RatioCompatibilityTau, ...
                eps);
            
            ratioScore(validMask) = ...
                exp( ...
                -logDifference ./ tau);
            
            ratioScore = ...
                max( ...
                0, ...
                min( ...
                1, ...
                ratioScore));
            
        end
        
        
        function resetSpectrumAxis(app, ax) %#ok<MANU>
            
            cla(ax, 'reset');
            
            ax.Box = 'on';
            ax.XGrid = 'off';
            ax.YGrid = 'off';
            
            ax.YDir = 'normal';
            ax.YAxisLocation = 'right';
            
            ax.TickDir = 'in';
            ax.Layer = 'top';
            
            ax.FontSize = 10;
            
            xlabel(ax, 'Wavenumber (cm^{-1})');
            ylabel(ax, 'Intensity');
            
        end
        
        
        function setNiceSpectralLimits(app, ax, x, y) %#ok<MANU>
            
            x = double(x(:));
            y = double(y(:));
            
            x = x(isfinite(x));
            y = y(isfinite(y));
            
            if ~isempty(x)
                
                xmin = min(x);
                xmax = max(x);
                
                if xmin < xmax
                    xlim(ax, [xmin xmax]);
                end
            end
            
            if isempty(y)
                return
            end
            
            lo = prctile(y, 1);
            hi = prctile(y, 99);
            
            if ~isfinite(lo) || ~isfinite(hi) || lo >= hi
                
                lo = min(y);
                hi = max(y);
            end
            
            if lo >= hi
                
                lo = lo - 0.5;
                hi = hi + 0.5;
            end
            
            pad = 0.08 * (hi - lo);
            
            ylim(ax, [lo - pad, hi + pad]);
            
        end
        
        
        function safeName = makeSafeFileName(app, nameIn) %#ok<MANU>
            
            safeName = ...
                regexprep( ...
                char(string(nameIn)), ...
                '[^\w\-]', ...
                '_');
            
        end

        function [Xraw, Xnorm, pixelIdx] = getValidClusterSpectra(app)
            
            Xraw = [];
            Xnorm = [];
            pixelIdx = [];
            
            if isempty(app.RamanCube) || ...
                    isempty(app.ValidMask2D)
                
                return
            end
            
            cube = double(app.RamanCube);
            
            [ny, nx, nWn] = size(cube);
            
            pixelIdx = find(app.ValidMask2D);
            
            if isempty(pixelIdx)
                return
            end
            
            Xall = reshape(cube, ny * nx, nWn);
            
            Xraw = Xall(pixelIdx, :);
            
            good = ...
                all(isfinite(Xraw), 2);
            
            Xraw = Xraw(good, :);
            pixelIdx = pixelIdx(good);
            
            if isempty(Xraw)
                return
            end
            
            % =============================================================
            % ROBUST ROW NORMALIZATION
            %
            % Preserve Xraw for mean spectra.
            % Use Xnorm only for PCA and K-means.
            % =============================================================
            
            rowMedian = ...
                median(Xraw, 2, 'omitnan');
            
            Xnorm = ...
                Xraw - rowMedian;
            
            rowNorm = ...
                sqrt(sum(Xnorm .^ 2, 2));
            
            badNorm = ...
                rowNorm <= eps | ...
                ~isfinite(rowNorm);
            
            rowNorm(badNorm) = 1;
            
            Xnorm = ...
                Xnorm ./ rowNorm;
            
            Xnorm(~isfinite(Xnorm)) = 0;
            
        end
        
        
        function [selectedIndex, updatedResults, selectionSummary] = ...
                selectOptimalPCCandidate( ...
                app, ...
                candidateResults)
            
            % =============================================================
            % SELECT OPTIMAL PCA DIMENSION
            %
            % Scientific selection rule:
            %
            %   1. Prefer candidates with OverallMedianARI >= minimum ARI.
            %
            %   2. Define an ARI plateau:
            %        ARI >= best stable ARI - ARI tolerance.
            %
            %   3. Inside that plateau, retain candidates whose assignment
            %      confidence is at least a fixed fraction of the best
            %      confidence in the plateau.
            %
            %   4. Select the smallest remaining PC count.
            %
            % This avoids arbitrary weighted scores and implements
            % stability + confidence + parsimony.
            % =============================================================
            
            if isempty(candidateResults)
                
                error( ...
                    'No PCA candidate result is available for selection.');
            end
            
            numberCandidates = ...
                numel(candidateResults);
            
            pcValues = ...
                double([candidateResults.PCCount]);
            
            ariValues = ...
                double([candidateResults.OverallMedianARI]);
            
            confidenceValues = ...
                double([candidateResults.MedianConfidence]);
            
            validCandidates = ...
                isfinite(pcValues) & ...
                isfinite(ariValues) & ...
                isfinite(confidenceValues);
            
            if ~any(validCandidates)
                
                error( ...
                    ['No PCA candidate has finite ARI and assignment ' ...
                    'confidence values.']);
            end
            
            updatedResults = ...
                candidateResults;
            
            for candidateIndex = 1:numberCandidates
                
                updatedResults(candidateIndex).Status = ...
                    'Not selected';
                
                updatedResults(candidateIndex).SelectionReason = ...
                    '';
            end
            
            % =============================================================
            % 1. STABILITY FILTER
            % =============================================================
            
            stableMask = ...
                validCandidates & ...
                ariValues >= app.PCASelectionMinimumARI;
            
            usedFallback = ...
                false;
            
            if any(stableMask)
                
                selectionPool = ...
                    find(stableMask);
                
                stabilityDescription = ...
                    sprintf( ...
                    'ARI >= %.3f', ...
                    app.PCASelectionMinimumARI);
                
            else
                
                % No candidate reached the absolute stability threshold.
                % Do not abort the complete clustering process. Select from
                % the best available ARI region, but report the fallback.
                usedFallback = ...
                    true;
                
                bestAvailableARI = ...
                    max(ariValues(validCandidates));
                
                fallbackMask = ...
                    validCandidates & ...
                    ariValues >= ...
                    bestAvailableARI - ...
                    app.PCASelectionARITolerance;
                
                selectionPool = ...
                    find(fallbackMask);
                
                stabilityDescription = ...
                    sprintf( ...
                    ['No candidate reached ARI %.3f; fallback to best ' ...
                    'available ARI plateau'], ...
                    app.PCASelectionMinimumARI);
            end
            
            % =============================================================
            % 2. ARI PLATEAU
            % =============================================================
            
            maximumPoolARI = ...
                max(ariValues(selectionPool));
            
            plateauMask = ...
                false(1, numberCandidates);
            
            plateauMask(selectionPool) = ...
                ariValues(selectionPool) >= ...
                maximumPoolARI - ...
                app.PCASelectionARITolerance;
            
            plateauIndices = ...
                find(plateauMask);
            
            if isempty(plateauIndices)
                
                error( ...
                    'The PCA stability plateau could not be determined.');
            end
            
            % =============================================================
            % 3. CONFIDENCE RETENTION
            % =============================================================
            
            bestPlateauConfidence = ...
                max(confidenceValues(plateauIndices));
            
            minimumAcceptedConfidence = ...
                app.PCASelectionConfidenceRetention * ...
                bestPlateauConfidence;
            
            confidenceAcceptedMask = ...
                plateauMask & ...
                confidenceValues >= ...
                minimumAcceptedConfidence;
            
            acceptedIndices = ...
                find(confidenceAcceptedMask);
            
            if isempty(acceptedIndices)
                
                % Numerical fallback. This should rarely occur because the
                % best-confidence candidate necessarily reaches 100% of
                % the best plateau confidence.
                [~, bestConfidencePosition] = ...
                    max(confidenceValues(plateauIndices));
                
                acceptedIndices = ...
                    plateauIndices(bestConfidencePosition);
            end
            
            % =============================================================
            % 4. PARSIMONY
            %
            % Choose the lowest-dimensional candidate remaining after the
            % stability and confidence filters.
            % =============================================================
            
            acceptedPCValues = ...
                pcValues(acceptedIndices);
            
            minimumPC = ...
                min(acceptedPCValues);
            
            smallestCandidates = ...
                acceptedIndices( ...
                acceptedPCValues == minimumPC);
            
            if numel(smallestCandidates) > 1
                
                % Normally PCCount values are unique. Keep deterministic
                % behavior if duplicate records exist.
                duplicateARI = ...
                    ariValues(smallestCandidates);
                
                bestDuplicateARI = ...
                    max(duplicateARI);
                
                duplicateCandidates = ...
                    smallestCandidates( ...
                    duplicateARI == bestDuplicateARI);
                
                if numel(duplicateCandidates) > 1
                    
                    duplicateConfidence = ...
                        confidenceValues(duplicateCandidates);
                    
                    [~, duplicatePosition] = ...
                        max(duplicateConfidence);
                    
                    selectedIndex = ...
                        duplicateCandidates(duplicatePosition);
                    
                else
                    
                    selectedIndex = ...
                        duplicateCandidates(1);
                end
                
            else
                
                selectedIndex = ...
                    smallestCandidates(1);
            end
            
            % =============================================================
            % 5. STATUS AND REASONS
            % =============================================================
            
            for candidateIndex = 1:numberCandidates
                
                if ~validCandidates(candidateIndex)
                    
                    updatedResults(candidateIndex).Status = ...
                        'Invalid';
                    
                    updatedResults(candidateIndex).SelectionReason = ...
                        'Non-finite ARI or confidence.';
                    
                elseif candidateIndex == selectedIndex
                    
                    updatedResults(candidateIndex).Status = ...
                        'SELECTED';
                    
                    updatedResults(candidateIndex).SelectionReason = ...
                        sprintf( ...
                        ['Inside the ARI plateau; confidence >= %.1f%% ' ...
                        'of plateau maximum; lowest accepted PC count.'], ...
                        100 * app.PCASelectionConfidenceRetention);
                    
                elseif ~stableMask(candidateIndex) && ...
                        ~usedFallback
                    
                    updatedResults(candidateIndex).Status = ...
                        'Rejected - unstable';
                    
                    updatedResults(candidateIndex).SelectionReason = ...
                        sprintf( ...
                        'Overall median ARI %.3f is below %.3f.', ...
                        ariValues(candidateIndex), ...
                        app.PCASelectionMinimumARI);
                    
                elseif ~plateauMask(candidateIndex)
                    
                    updatedResults(candidateIndex).Status = ...
                        'Rejected - outside ARI plateau';
                    
                    updatedResults(candidateIndex).SelectionReason = ...
                        sprintf( ...
                        ['ARI %.3f is more than %.3f below the best ' ...
                        'eligible ARI %.3f.'], ...
                        ariValues(candidateIndex), ...
                        app.PCASelectionARITolerance, ...
                        maximumPoolARI);
                    
                elseif ~confidenceAcceptedMask(candidateIndex)
                    
                    updatedResults(candidateIndex).Status = ...
                        'Rejected - low confidence';
                    
                    updatedResults(candidateIndex).SelectionReason = ...
                        sprintf( ...
                        ['Median confidence %.3f is below the retained ' ...
                        'plateau threshold %.3f.'], ...
                        confidenceValues(candidateIndex), ...
                        minimumAcceptedConfidence);
                    
                else
                    
                    updatedResults(candidateIndex).Status = ...
                        'Accepted plateau alternative';
                    
                    updatedResults(candidateIndex).SelectionReason = ...
                        sprintf( ...
                        ['Meets stability and confidence criteria, but uses ' ...
                        'more PCs than the selected parsimonious solution.']);
                end
            end
            
            % =============================================================
            % 6. RETURN SUMMARY
            % =============================================================
            
            selectedResult = ...
                updatedResults(selectedIndex);
            
            selectionSummary = struct();
            
            selectionSummary.SelectedIndex = ...
                selectedIndex;
            
            selectionSummary.SelectedPC = ...
                selectedResult.PCCount;
            
            selectionSummary.SelectedARI = ...
                selectedResult.OverallMedianARI;
            
            selectionSummary.SelectedConfidence = ...
                selectedResult.MedianConfidence;
            
            selectionSummary.MaximumEligibleARI = ...
                maximumPoolARI;
            
            selectionSummary.MinimumAcceptedConfidence = ...
                minimumAcceptedConfidence;
            
            selectionSummary.UsedFallback = ...
                usedFallback;
            
            selectionSummary.StabilityDescription = ...
                stabilityDescription;
            
            selectionSummary.SelectionRule = ...
                ['Stable ARI plateau + confidence retention + ' ...
                'minimum-dimensional solution'];
            
        end
        
        
        function candidateTable = ...
                buildPCCandidateEvidenceTable( ...
                app, ...
                candidateResults) %#ok<INUSD>
            
            % =============================================================
            % FLATTEN PCA-CANDIDATE STRUCTURES INTO A CSV-SAFE TABLE
            % =============================================================
            
            numberCandidates = ...
                numel(candidateResults);
            
            PCs = ...
                nan(numberCandidates, 1);
            
            VariancePercent = ...
                nan(numberCandidates, 1);
            
            Runs = ...
                nan(numberCandidates, 1);
            
            OverallMedianARI = ...
                nan(numberCandidates, 1);
            
            MedoidMedianARI = ...
                nan(numberCandidates, 1);
            
            MedianConfidence = ...
                nan(numberCandidates, 1);
            
            LowConfidenceFraction = ...
                nan(numberCandidates, 1);
            
            MedianLargestComponent = ...
                nan(numberCandidates, 1);
            
            MedianIsolatedFraction = ...
                nan(numberCandidates, 1);
            
            TotalConnectedComponents = ...
                nan(numberCandidates, 1);
            
            SelectedRun = ...
                nan(numberCandidates, 1);
            
            SelectedSeed = ...
                nan(numberCandidates, 1);
            
            Stage = ...
                strings(numberCandidates, 1);
            
            Stability = ...
                strings(numberCandidates, 1);
            
            Status = ...
                strings(numberCandidates, 1);
            
            SelectionReason = ...
                strings(numberCandidates, 1);
            
            for candidateIndex = 1:numberCandidates
                
                R = ...
                    candidateResults(candidateIndex);
                
                PCs(candidateIndex) = ...
                    app.getStructNumericField( ...
                    R, ...
                    'PCCount');
                
                VariancePercent(candidateIndex) = ...
                    app.getStructNumericField( ...
                    R, ...
                    'ExplainedVariance');
                
                Runs(candidateIndex) = ...
                    app.getStructNumericField( ...
                    R, ...
                    'NumberRuns');
                
                OverallMedianARI(candidateIndex) = ...
                    app.getStructNumericField( ...
                    R, ...
                    'OverallMedianARI');
                
                MedoidMedianARI(candidateIndex) = ...
                    app.getStructNumericField( ...
                    R, ...
                    'MedoidMedianARI');
                
                MedianConfidence(candidateIndex) = ...
                    app.getStructNumericField( ...
                    R, ...
                    'MedianConfidence');
                
                LowConfidenceFraction(candidateIndex) = ...
                    app.getStructNumericField( ...
                    R, ...
                    'LowConfidenceFraction');
                
                MedianLargestComponent(candidateIndex) = ...
                    app.getStructNumericField( ...
                    R, ...
                    'MedianLargestComponentFraction');
                
                MedianIsolatedFraction(candidateIndex) = ...
                    app.getStructNumericField( ...
                    R, ...
                    'MedianIsolatedComponentFraction');
                
                TotalConnectedComponents(candidateIndex) = ...
                    app.getStructNumericField( ...
                    R, ...
                    'TotalConnectedComponents');
                
                SelectedRun(candidateIndex) = ...
                    app.getStructNumericField( ...
                    R, ...
                    'SelectedRun');
                
                SelectedSeed(candidateIndex) = ...
                    app.getStructNumericField( ...
                    R, ...
                    'SelectedSeed');
                
                Stage(candidateIndex) = ...
                    app.getStructStringField( ...
                    R, ...
                    'Stage');
                
                Stability(candidateIndex) = ...
                    app.getStructStringField( ...
                    R, ...
                    'StabilityLevel');
                
                Status(candidateIndex) = ...
                    app.getStructStringField( ...
                    R, ...
                    'Status');
                
                SelectionReason(candidateIndex) = ...
                    app.getStructStringField( ...
                    R, ...
                    'SelectionReason');
            end
            
            candidateTable = ...
                table( ...
                PCs, ...
                VariancePercent, ...
                Runs, ...
                OverallMedianARI, ...
                MedoidMedianARI, ...
                MedianConfidence, ...
                LowConfidenceFraction, ...
                MedianLargestComponent, ...
                MedianIsolatedFraction, ...
                TotalConnectedComponents, ...
                SelectedRun, ...
                SelectedSeed, ...
                Stage, ...
                Stability, ...
                Status, ...
                SelectionReason);
            
        end
        
        
        function writeClusterInputProvenance( ...
                app, ...
                provenancePath)
            
            fileID = ...
                fopen( ...
                provenancePath, ...
                'w');
            
            if fileID < 0
                
                error( ...
                    'single_extract:ProvenanceOpenFailed', ...
                    ['Could not open provenance file:' newline ...
                    provenancePath]);
            end
            
            cleanupObject = ...
                onCleanup( ...
                @() fclose(fileID)); %#ok<NASGU>
            
            fprintf(fileID, ...
                'CLUSTER INPUT PROVENANCE\n');
            
            fprintf(fileID, ...
                '============================================================\n\n');
            
            fprintf(fileID, ...
                'Created: %s\n', ...
                char(datetime('now', ...
                'Format', 'yyyy-MM-dd HH:mm:ss.SSS')));
            
            fprintf(fileID, ...
                'MATLAB version: %s\n', ...
                version);
            
            fprintf(fileID, ...
                'Operating system: %s\n', ...
                system_dependent('getos'));
            
            fprintf(fileID, ...
                'Scan name: %s\n', ...
                app.CurrentScanName);
            
            fprintf(fileID, ...
                'Scan ID: %s\n', ...
                app.CurrentScanID);
            
            fprintf(fileID, ...
                'Source MAT path: %s\n', ...
                app.SourceMatPath);
            
            fprintf(fileID, ...
                'Output root: %s\n', ...
                app.OutputRoot);
            
            fprintf(fileID, ...
                'Cube size: %s\n', ...
                mat2str(size(app.RamanCube)));
            
            fprintf(fileID, ...
                'Wavenumber count: %d\n', ...
                numel(app.Wavenumber));
            
            fprintf(fileID, ...
                'Valid pixels: %d\n', ...
                nnz(app.ValidMask2D));
            
            fprintf(fileID, ...
                'Artifact-mask pixels: %d\n', ...
                nnz(app.ArtifactMask2D));
            
        end
        
        
        function evidenceInfo = ...
                saveClusterSelectionEvidence( ...
                app, ...
                params, ...
                selectionSummary)
            
            % =============================================================
            % SAVE COMPLETE CLUSTER-SELECTION EVIDENCE PACKAGE
            %
            % No evidence window is opened. The function creates files only.
            % =============================================================
            
            evidenceInfo = struct();
            
            evidenceInfo.Completed = ...
                false;
            
            evidenceInfo.RunFolder = ...
                '';
            
            evidenceInfo.EvidenceMAT = ...
                '';
            
            evidenceInfo.ModelMAT = ...
                '';
            
            evidenceInfo.Warning = ...
                '';
            
            if ~app.ClusterEvidenceAutoSave
                
                evidenceInfo.Warning = ...
                    'Automatic evidence saving is disabled.';
                
                return
            end
            
            [runFolder, figureFolder] = ...
                app.createClusterEvidenceRunFolder();
            
            candidateTable = ...
                app.buildPCCandidateEvidenceTable( ...
                app.PCASelectionResults);
            
            selectedRunTable = ...
                app.buildSelectedRunEvidenceTable();
            
            candidateCSV = ...
                fullfile( ...
                runFolder, ...
                'pc_candidate_metrics.csv');
            
            mapAgreementCSV = ...
                fullfile( ...
                runFolder, ...
                'pc_map_agreement.csv');
            
            selectedRunCSV = ...
                fullfile( ...
                runFolder, ...
                'selected_run_metrics.csv');
            
            summaryPath = ...
                fullfile( ...
                runFolder, ...
                'cluster_selection_summary.txt');
            
            provenancePath = ...
                fullfile( ...
                runFolder, ...
                'input_provenance.txt');
            
            evidencePath = ...
                fullfile( ...
                runFolder, ...
                'cluster_selection_evidence.mat');
            
            modelPath = ...
                fullfile( ...
                runFolder, ...
                'selected_cluster_model.mat');
            
            writetable( ...
                candidateTable, ...
                candidateCSV);
            
            writetable( ...
                selectedRunTable, ...
                selectedRunCSV);
            
            pcValues = ...
                double( ...
                [app.PCASelectionResults.PCCount]);
            
            agreementTable = ...
                array2table( ...
                app.PCASelectionMapAgreement, ...
                'VariableNames', ...
                matlab.lang.makeValidName( ...
                compose( ...
                'PC_%d', ...
                pcValues)), ...
                'RowNames', ...
                cellstr( ...
                compose( ...
                'PC_%d', ...
                pcValues)));
            
            writetable( ...
                agreementTable, ...
                mapAgreementCSV, ...
                'WriteRowNames', ...
                true);
            
            app.writeClusterEvidenceSummary( ...
                summaryPath, ...
                params, ...
                selectionSummary, ...
                runFolder);
            
            app.writeClusterInputProvenance( ...
                provenancePath);
            
            app.saveSelectedClusterModel( ...
                modelPath, ...
                params, ...
                selectionSummary);
            
            app.saveFullClusterEvidence( ...
                evidencePath, ...
                params, ...
                selectionSummary, ...
                candidateTable, ...
                selectedRunTable);
            
            app.saveClusterEvidenceFigures( ...
                figureFolder);
            
            metadataRoot = ...
                app.getClusterMetadataRoot();
            
            latestFile = ...
                fullfile( ...
                metadataRoot, ...
                'latest_run.txt');
            
            latestFileID = ...
                fopen( ...
                latestFile, ...
                'w');
            
            if latestFileID >= 0
                
                fprintf( ...
                    latestFileID, ...
                    '%s\n', ...
                    runFolder);
                
                fclose(latestFileID);
            end
            
            app.ClusterEvidenceFolder = ...
                runFolder;
            
            app.ClusterEvidenceLatestFile = ...
                latestFile;
            
            evidenceInfo.Completed = ...
                true;
            
            evidenceInfo.RunFolder = ...
                runFolder;
            
            evidenceInfo.EvidenceMAT = ...
                evidencePath;
            
            evidenceInfo.ModelMAT = ...
                modelPath;
            
        end
        
        
        function splitDepth = getClusterSplitDepth(app, clusterID)
            
            % =============================================================
            % RETURN HIERARCHICAL SPLIT DEPTH
            %
            % ParentID = 0 means an original/root cluster.
            % =============================================================
            
            splitDepth = ...
                0;
            
            currentID = ...
                double(clusterID);
            
            visitedIDs = ...
                [];
            
            while isfinite(currentID) && ...
                    currentID > 0
                
                if any(visitedIDs == currentID)
                    
                    error( ...
                        ['A circular ParentID relationship was detected ' ...
                        'in the cluster hierarchy.']);
                end
                
                visitedIDs(end + 1) = ...
                    currentID; %#ok<AGROW>
                
                clusterIndex = ...
                    app.findClusterIndexByID(currentID);
                
                if isempty(clusterIndex)
                    break
                end
                
                parentID = ...
                    double( ...
                    app.Clusters(clusterIndex).ParentID);
                
                if ~isfinite(parentID) || ...
                        parentID <= 0
                    
                    break
                end
                
                splitDepth = ...
                    splitDepth + 1;
                
                currentID = ...
                    parentID;
            end
            
        end
        
        
        function saveFullClusterEvidence( ...
                app, ...
                evidencePath, ...
                params, ...
                selectionSummary, ...
                candidateTable, ...
                selectedRunTable)
            
            % =============================================================
            % COMPLETE AUDIT PACKAGE
            %
            % This intentionally stores all candidate/run structures,
            % including candidate medoid labels and selected-PC run labels.
            % =============================================================
            
            Evidence = struct();
            
            Evidence.FormatVersion = ...
                app.ClusterEvidenceVersion;
            
            Evidence.CreatedAt = ...
                datetime('now');
            
            Evidence.Scan = struct();
            
            Evidence.Scan.Name = ...
                app.CurrentScanName;
            
            Evidence.Scan.ID = ...
                app.CurrentScanID;
            
            Evidence.Scan.SourceMatPath = ...
                app.SourceMatPath;
            
            Evidence.Scan.OutputRoot = ...
                app.OutputRoot;
            
            Evidence.Scan.CubeSize = ...
                size(app.RamanCube);
            
            Evidence.Scan.ValidPixelCount = ...
                nnz(app.ValidMask2D);
            
            Evidence.Scan.ArtifactPixelCount = ...
                nnz(app.ArtifactMask2D);
            
            Evidence.Parameters = ...
                params;
            
            Evidence.SelectionThresholds = struct( ...
                'MinimumARI', ...
                app.PCASelectionMinimumARI, ...
                'ARITolerance', ...
                app.PCASelectionARITolerance, ...
                'ConfidenceRetention', ...
                app.PCASelectionConfidenceRetention, ...
                'MaximumFragmentationIncrease', ...
                app.PCASelectionMaximumFragmentationIncrease);
            
            Evidence.SelectionSummary = ...
                selectionSummary;
            
            Evidence.PCCandidateResults = ...
                app.PCASelectionResults;
            
            Evidence.PCCandidateTable = ...
                candidateTable;
            
            Evidence.PCMapAgreement = ...
                app.PCASelectionMapAgreement;
            
            Evidence.SelectedPCIndex = ...
                app.PCASelectionSelectedIndex;
            
            Evidence.SelectedPC = ...
                app.PCASelectionSelectedPC;
            
            Evidence.SelectedPCRunResults = ...
                app.ClusterRunResults;
            
            Evidence.SelectedPCRunTable = ...
                selectedRunTable;
            
            Evidence.SelectedPCPairwiseARI = ...
                app.ClusterPairwiseARI;
            
            Evidence.SelectedRun = ...
                app.ClusterSelectedRun;
            
            Evidence.SelectedSeed = ...
                app.ClusterSelectedSeed;
            
            Evidence.SelectedOverallMedianARI = ...
                app.ClusterOverallMedianARI;
            
            Evidence.SelectedMedoidMedianARI = ...
                app.ClusterMedoidMedianARI;
            
            Evidence.SelectedStabilityLevel = ...
                app.ClusterStabilityLevel;
            
            Evidence.PCAModel = struct();
            
            Evidence.PCAModel.Coefficients = ...
                app.PCAcoeff;
            
            Evidence.PCAModel.Mean = ...
                app.PCAmu;
            
            Evidence.PCAModel.Explained = ...
                app.PCAExplained;
            
            Evidence.PCAModel.NumPCUsed = ...
                app.NumPCUsed;
            
            Evidence.TrainingSubset = struct();
            
            Evidence.TrainingSubset.LocalIndices = ...
                app.TrainingLocalIdx;
            
            Evidence.TrainingSubset.LinearPixelIndices = ...
                app.TrainingLinearIdx;
            
            Evidence.FinalOutput = struct();
            
            Evidence.FinalOutput.MetricPixelIdx = ...
                app.MetricPixelIdx;
            
            Evidence.FinalOutput.MetricLabels = ...
                app.MetricLabels;
            
            Evidence.FinalOutput.ClusterCentroids = ...
                app.ClusterCentroids;
            
            Evidence.FinalOutput.PixelConfidence = ...
                app.PixelConfidence;
            
            Evidence.FinalOutput.PixelConfidenceMap = ...
                app.PixelConfidenceMap;
            
            Evidence.FinalOutput.ClusterLabelMap = ...
                app.ClusterLabelMap;
            
            Evidence.FinalOutput.Clusters = ...
                app.Clusters;
            
            Evidence.GlobalClusterMetrics = ...
                app.GlobalClusterMetrics;
            
            save( ...
                evidencePath, ...
                'Evidence', ...
                '-v7.3');
            
        end
        
        
        function saveClusterEvidenceFigures( ...
                app, ...
                figureFolder)
            
            if ~app.ClusterEvidenceSaveFigures
                return
            end
            
            app.saveSelectedClusterMapEvidence( ...
                fullfile( ...
                figureFolder, ...
                'selected_cluster_map.png'));
            
            app.saveSelectedConfidenceEvidence( ...
                fullfile( ...
                figureFolder, ...
                'selected_confidence_map.png'));
            
            app.savePCSelectionMetricsEvidence( ...
                fullfile( ...
                figureFolder, ...
                'pc_selection_metrics.png'));
            
            app.savePCMapAgreementEvidence( ...
                fullfile( ...
                figureFolder, ...
                'pc_map_agreement.png'));
            
            app.saveSelectedRunStabilityEvidence( ...
                fullfile( ...
                figureFolder, ...
                'selected_run_stability.png'));
            
        end
        
        
        function saveSelectedConfidenceEvidence( ...
                app, ...
                outputPath)
            
            evidenceFigure = ...
                figure( ...
                'Visible', ...
                'off', ...
                'Color', ...
                'w', ...
                'Position', ...
                [100 100 900 760]);
            
            cleanupObject = ...
                onCleanup( ...
                @() delete(evidenceFigure)); %#ok<NASGU>
            
            evidenceAxis = ...
                axes( ...
                evidenceFigure);
            
            imagesc( ...
                evidenceAxis, ...
                app.PixelConfidenceMap, ...
                [0 1]);
            
            axis(evidenceAxis, 'image');
            
            evidenceAxis.YDir = ...
                'normal';
            
            evidenceAxis.XTick = [];
            evidenceAxis.YTick = [];
            
            colormap( ...
                evidenceAxis, ...
                turbo(256));
            
            colorbar(evidenceAxis);
            
            title( ...
                evidenceAxis, ...
                { ...
                app.CurrentScanName, ...
                sprintf( ...
                'Selected-map centroid confidence | median=%.3f', ...
                median(app.PixelConfidence, 'omitnan'))}, ...
                'Interpreter', ...
                'none');
            
            exportgraphics( ...
                evidenceFigure, ...
                outputPath, ...
                'Resolution', ...
                300);
            
        end
        
        
        function splitQC = evaluateSelectedLocalSplit( ...
                app, ...
                localScores, ...
                localPixelIdx, ...
                numberOfSubclusters, ...
                stability)
            
            % =============================================================
            % QC OF THE SELECTED MEDOID SPLIT
            %
            % Checks:
            %   - all parent pixels are represented exactly once;
            %   - no requested child is empty;
            %   - no child is too small;
            %   - child-centroid assignment is sufficiently clear.
            % =============================================================
            
            selectedResult = ...
                stability.RunResults( ...
                stability.SelectedRun);
            
            selectedLabels = ...
                double( ...
                selectedResult.LabelsAll(:));
            
            selectedCentroids = ...
                double( ...
                selectedResult.Centroids);
            
            numberPixels = ...
                size(localScores, 1);
            
            if numel(selectedLabels) ~= numberPixels || ...
                    numel(localPixelIdx) ~= numberPixels
                
                error( ...
                    ['The selected split labels do not match the number ' ...
                    'of parent pixels.']);
            end
            
            childSizes = ...
                accumarray( ...
                selectedLabels, ...
                1, ...
                [numberOfSubclusters 1]);
            
            childFractions = ...
                childSizes ./ ...
                max(1, numberPixels);
            
            distanceMatrix = ...
                pdist2( ...
                localScores, ...
                selectedCentroids, ...
                'squaredeuclidean');
            
            sortedDistances = ...
                sort( ...
                distanceMatrix, ...
                2, ...
                'ascend');
            
            if size(sortedDistances, 2) >= 2
                
                nearestDistance = ...
                    sortedDistances(:, 1);
                
                secondDistance = ...
                    sortedDistances(:, 2);
                
                assignmentConfidence = ...
                    1 - ...
                    nearestDistance ./ ...
                    max(secondDistance, eps);
                
            else
                
                assignmentConfidence = ...
                    ones(numberPixels, 1);
            end
            
            assignmentConfidence = ...
                max( ...
                0, ...
                min( ...
                1, ...
                assignmentConfidence));
            
            medianConfidence = ...
                median( ...
                assignmentConfidence, ...
                'omitnan');
            
            minimumRequiredPixels = ...
                max( ...
                app.SplitMinimumChildPixels, ...
                ceil( ...
                app.SplitMinimumChildFraction * ...
                numberPixels));
            
            smallChildMask = ...
                childSizes < minimumRequiredPixels;
            
            % The label vector itself is a complete non-overlapping
            % partition when every value is in 1:numberOfSubclusters.
            validLabelRange = ...
                all( ...
                selectedLabels >= 1 & ...
                selectedLabels <= numberOfSubclusters);
            
            splitQC = struct();
            
            splitQC.SelectedLabels = ...
                uint16(selectedLabels);
            
            splitQC.SelectedCentroids = ...
                selectedCentroids;
            
            splitQC.ChildSizes = ...
                childSizes;
            
            splitQC.ChildFractions = ...
                childFractions;
            
            splitQC.MinimumRequiredPixels = ...
                minimumRequiredPixels;
            
            splitQC.SmallChildMask = ...
                smallChildMask;
            
            splitQC.AssignmentConfidence = ...
                assignmentConfidence;
            
            splitQC.MedianConfidence = ...
                medianConfidence;
            
            splitQC.ValidLabelRange = ...
                validLabelRange;
            
            splitQC.AllChildrenNonempty = ...
                all(childSizes > 0);
            
            splitQC.PixelCountPreserved = ...
                sum(childSizes) == numberPixels;
            
            splitQC.PassesSizeRule = ...
                ~any(smallChildMask);
            
            splitQC.PassesConfidenceRule = ...
                isfinite(medianConfidence) && ...
                medianConfidence >= ...
                app.SplitMinimumMedianConfidence;
            
            splitQC.PassesIntegrityRules = ...
                splitQC.ValidLabelRange && ...
                splitQC.AllChildrenNonempty && ...
                splitQC.PixelCountPreserved;
            
        end
        
        
        function saveSelectedRunStabilityEvidence( ...
                app, ...
                outputPath)
            
            if isempty(app.ClusterRunResults)
                return
            end
            
            medianARIValues = ...
                double([app.ClusterRunResults.MedianARI]);
            
            sseValues = ...
                double([app.ClusterRunResults.TotalSSE]);
            
            runValues = ...
                1:numel(app.ClusterRunResults);
            
            evidenceFigure = ...
                figure( ...
                'Visible', ...
                'off', ...
                'Color', ...
                'w', ...
                'Position', ...
                [100 100 1050 720]);
            
            cleanupObject = ...
                onCleanup( ...
                @() delete(evidenceFigure)); %#ok<NASGU>
            
            layout = ...
                tiledlayout( ...
                evidenceFigure, ...
                2, ...
                1, ...
                'TileSpacing', ...
                'compact', ...
                'Padding', ...
                'compact');
            
            ariAxis = ...
                nexttile(layout);
            
            plot( ...
                ariAxis, ...
                runValues, ...
                medianARIValues, ...
                '-o', ...
                'LineWidth', ...
                1.4);
            
            hold(ariAxis, 'on');
            
            xline( ...
                ariAxis, ...
                app.ClusterSelectedRun, ...
                '--', ...
                sprintf( ...
                'Selected run %d', ...
                app.ClusterSelectedRun));
            
            hold(ariAxis, 'off');
            
            grid(ariAxis, 'on');
            
            xlabel(ariAxis, 'K-means run');
            ylabel(ariAxis, 'Median ARI to other runs');
            
            title( ...
                ariAxis, ...
                sprintf( ...
                'Selected PC=%d | run-level partition stability', ...
                app.NumPCUsed));
            
            sseAxis = ...
                nexttile(layout);
            
            plot( ...
                sseAxis, ...
                runValues, ...
                sseValues, ...
                '-o', ...
                'LineWidth', ...
                1.4);
            
            hold(sseAxis, 'on');
            
            xline( ...
                sseAxis, ...
                app.ClusterSelectedRun, ...
                '--', ...
                sprintf( ...
                'Selected run %d', ...
                app.ClusterSelectedRun));
            
            hold(sseAxis, 'off');
            
            grid(sseAxis, 'on');
            
            xlabel(sseAxis, 'K-means run');
            ylabel(sseAxis, 'Training SSE');
            
            title( ...
                sseAxis, ...
                'Training SSE across independent seeds');
            
            title( ...
                layout, ...
                { ...
                app.CurrentScanName, ...
                sprintf( ...
                'Overall median pairwise ARI = %.4f', ...
                app.ClusterOverallMedianARI)}, ...
                'Interpreter', ...
                'none');
            
            exportgraphics( ...
                evidenceFigure, ...
                outputPath, ...
                'Resolution', ...
                300);
            
        end
        
        
        function runClusterSegmentation(app, params)
            
            % =============================================================
            % AUTOMATIC PCA DIMENSION + MULTI-SEED K-MEANS
            %
            % Automatic mode:
            %
            %   1. Select one fixed training subset.
            %   2. Fit PCA exactly once up to the maximum requested PC.
            %   3. Coarse-screen every candidate PC dimension.
            %   4. Select top coarse candidates.
            %   5. Final-validate top candidates with more seeds.
            %   6. Select PC count using:
            %        stability plateau
            %        confidence retention
            %        parsimony
            %   7. Use the selected candidate's medoid run as final map.
            %
            % Fixed mode:
            %
            %   evaluates exactly one PC dimension using the final number
            %   of stability runs.
            % =============================================================
            
            % =============================================================
            % 1. VALIDATE RAMAN DATA
            % =============================================================
            
            if isempty(app.RamanCube)
                
                error( ...
                    'No Raman cube is available for clustering.');
            end
            
            if isempty(app.ValidMask2D) || ...
                    ~any(app.ValidMask2D(:))
                
                error( ...
                    'No valid pixel is available for clustering.');
            end
            
            [~, Xnorm, pixelIdx] = ...
                app.getValidClusterSpectra();
            
            if isempty(Xnorm)
                
                error( ...
                    'No finite Raman spectrum is available for clustering.');
            end
            
            numberPixels = ...
                size(Xnorm, 1);
            
            if numberPixels < params.K
                
                error( ...
                    'The number of valid spectra is smaller than K.');
            end
            
            % =============================================================
            % 2. FIXED TRAINING SUBSET
            %
            % The same rows are used for:
            %   - PCA fitting;
            %   - every PC candidate;
            %   - every K-means seed.
            % =============================================================
            
            rng(params.RandomSeed, 'twister');
            
            if numberPixels > params.TrainingMaxSpectra
                
                trainingLocalIdx = ...
                    sort( ...
                    randperm( ...
                    numberPixels, ...
                    params.TrainingMaxSpectra));
                
            else
                
                trainingLocalIdx = ...
                    (1:numberPixels)';
            end
            
            trainingLocalIdx = ...
                double(trainingLocalIdx(:));
            
            Xtrain = ...
                Xnorm(trainingLocalIdx, :);
            
            % =============================================================
            % 3. FIT PCA ONCE
            % =============================================================
            
            app.TextArea.Value = { ...
                'AUTOMATIC PCA-DIMENSION SEARCH'; ...
                '================================================'; ...
                ''; ...
                'Fitting one common PCA model...'; ...
                sprintf( ...
                'Training spectra: %d', ...
                numel(trainingLocalIdx)); ...
                sprintf( ...
                'Requested maximum PCs: %d', ...
                params.MaxPC)};
            
            drawnow;
            
            [coeffFull, scoreTrainFull, ~, ~, explained, mu] = ...
                pca( ...
                Xtrain, ...
                'Centered', ...
                true);
            
            maximumAvailablePC = ...
                min( ...
                [ ...
                params.MaxPC, ...
                size(coeffFull, 2), ...
                size(scoreTrainFull, 2)]);
            
            maximumAvailablePC = ...
                max(1, floor(maximumAvailablePC));
            
            coeffMaximum = ...
                coeffFull(:, 1:maximumAvailablePC);
            
            allScoresMaximum = ...
                (Xnorm - mu) * ...
                coeffMaximum;
            
            cumulativeExplained = ...
                cumsum(explained);
            
            % =============================================================
            % 4. FINALIZE PC CANDIDATES
            % =============================================================
            
            if params.AutomaticPCSelection
                
                pcCandidates = ...
                    double(params.PCCandidates(:).');
                
                pcCandidates = ...
                    pcCandidates( ...
                    pcCandidates >= 1 & ...
                    pcCandidates <= maximumAvailablePC);
                
                pcCandidates = ...
                    unique( ...
                    round(pcCandidates), ...
                    'sorted');
                
                if isempty(pcCandidates)
                    
                    error( ...
                        ['No requested PC candidate is available inside ' ...
                        'the fitted PCA model.']);
                end
                
                % Ensure the largest fitted dimension is represented.
                pcCandidates = ...
                    unique( ...
                    [ ...
                    pcCandidates(:); ...
                    maximumAvailablePC], ...
                    'sorted');
                
            else
                
                fixedPC = ...
                    min( ...
                    params.MaxPC, ...
                    maximumAvailablePC);
                
                pcCandidates = ...
                    fixedPC;
            end
            
            numberCandidates = ...
                numel(pcCandidates);
            
            % =============================================================
            % 5. COARSE SEARCH
            % =============================================================
            
            % Start with a genuinely empty structure.
            % The first evaluated candidate defines the complete schema.
            coarseResults = ...
                struct([]);
            
            if params.AutomaticPCSelection
                
                coarseRunCount = ...
                    params.CoarseRuns;
                
            else
                
                coarseRunCount = ...
                    params.StabilityRuns;
            end
            
            for candidateNumber = 1:numberCandidates
                
                currentPC = ...
                    pcCandidates(candidateNumber);
                
                app.TextArea.Value = { ...
                    'AUTOMATIC PCA-DIMENSION SEARCH'; ...
                    '================================================'; ...
                    ''; ...
                    'COARSE SCREENING'; ...
                    sprintf( ...
                    'Candidate %d / %d', ...
                    candidateNumber, ...
                    numberCandidates); ...
                    sprintf( ...
                    'Current PC count: %d', ...
                    currentPC); ...
                    sprintf( ...
                    'K-means runs: %d', ...
                    coarseRunCount); ...
                    sprintf( ...
                    'Explained variance: %.2f%%', ...
                    sum(explained(1:currentPC)))};
                
                drawnow;
                
                currentResult = ...
                    app.evaluateOnePCCandidate( ...
                    allScoresMaximum, ...
                    trainingLocalIdx, ...
                    pixelIdx, ...
                    currentPC, ...
                    coarseRunCount, ...
                    params, ...
                    'Coarse');
                
                currentResult.ExplainedVariance = ...
                    sum(explained(1:currentPC));
                
                if isempty(coarseResults)
                    
                    % The first candidate establishes the structure fields.
                    coarseResults = ...
                        currentResult;
                    
                else
                    
                    coarseResults(end + 1) = ...
                        currentResult; %#ok<AGROW>
                end
            end
            
            % =============================================================
            % 6. SELECT TOP COARSE CANDIDATES
            %
            % Ranking order:
            %   stable threshold first
            %   higher overall ARI
            %   higher assignment confidence
            %   lower PC count
            % =============================================================
            
            if params.AutomaticPCSelection
                
                coarseARI = ...
                    double([coarseResults.OverallMedianARI]);
                
                coarseConfidence = ...
                    double([coarseResults.MedianConfidence]);
                
                coarsePC = ...
                    double([coarseResults.PCCount]);
                
                coarseStable = ...
                    coarseARI >= ...
                    app.PCASelectionMinimumARI;
                
                rankingTable = ...
                    [ ...
                    -double(coarseStable(:)), ...
                    -coarseARI(:), ...
                    -coarseConfidence(:), ...
                    coarsePC(:), ...
                    (1:numberCandidates)'];
                
                rankingTable = ...
                    sortrows( ...
                    rankingTable, ...
                    [1 2 3 4]);
                
                rankedIndices = ...
                    rankingTable(:, 5);
                
                finalCandidateCount = ...
                    min( ...
                    params.FinalCandidateCount, ...
                    numberCandidates);
                
                finalCandidateIndices = ...
                    rankedIndices(1:finalCandidateCount);
                
                finalCandidateIndices = ...
                    finalCandidateIndices(:).';
                
            else
                
                finalCandidateIndices = ...
                    1;
            end
            
            % =============================================================
            % 7. FINAL VALIDATION
            %
            % Replace coarse records for top candidates with final records.
            % Non-final candidates remain available as coarse evidence.
            % =============================================================
            
            finalResults = ...
                coarseResults;
            
            for finalPosition = 1:numel(finalCandidateIndices)
                
                resultIndex = ...
                    finalCandidateIndices(finalPosition);
                
                currentPC = ...
                    coarseResults(resultIndex).PCCount;
                
                app.TextArea.Value = { ...
                    'AUTOMATIC PCA-DIMENSION SEARCH'; ...
                    '================================================'; ...
                    ''; ...
                    'FINAL VALIDATION'; ...
                    sprintf( ...
                    'Final candidate %d / %d', ...
                    finalPosition, ...
                    numel(finalCandidateIndices)); ...
                    sprintf( ...
                    'Current PC count: %d', ...
                    currentPC); ...
                    sprintf( ...
                    'Independent runs: %d', ...
                    params.StabilityRuns); ...
                    sprintf( ...
                    'Explained variance: %.2f%%', ...
                    sum(explained(1:currentPC)))};
                
                drawnow;
                
                validatedResult = ...
                    app.evaluateOnePCCandidate( ...
                    allScoresMaximum, ...
                    trainingLocalIdx, ...
                    pixelIdx, ...
                    currentPC, ...
                    params.StabilityRuns, ...
                    params, ...
                    'Final');
                
                validatedResult.ExplainedVariance = ...
                    sum(explained(1:currentPC));
                
                finalResults(resultIndex) = ...
                    validatedResult;
            end
            
            % Candidates not receiving final validation must not defeat a
            % fully validated candidate merely because their coarse estimate
            % was optimistic. Selection therefore uses final candidates only.
            selectionResults = ...
                finalResults(finalCandidateIndices);
            
            % =============================================================
            % 8. SELECT OPTIMAL PC
            % =============================================================
            
            [selectedLocalIndex, ...
                selectionResults, ...
                selectionSummary] = ...
                app.selectOptimalPCCandidate( ...
                selectionResults);
            
            selectedOriginalIndex = ...
                finalCandidateIndices(selectedLocalIndex);
            
            % Copy selection status back into the complete result array.
            for finalPosition = 1:numel(finalCandidateIndices)
                
                originalIndex = ...
                    finalCandidateIndices(finalPosition);
                
                finalResults(originalIndex) = ...
                    selectionResults(finalPosition);
            end
            
            % Mark coarse-only candidates explicitly.
            coarseOnlyIndices = ...
                setdiff( ...
                1:numberCandidates, ...
                finalCandidateIndices);
            
            for coarseIndex = coarseOnlyIndices
                
                finalResults(coarseIndex).Status = ...
                    'Coarse screening only';
                
                finalResults(coarseIndex).SelectionReason = ...
                    ['Not included among the top candidates for ' ...
                    'full final validation.'];
            end
            
            selectedCandidate = ...
                finalResults(selectedOriginalIndex);
            
            selectedPC = ...
                selectedCandidate.PCCount;
            
            % =============================================================
            % 9. CROSS-PC MEDOID AGREEMENT
            % =============================================================
            
            mapAgreement = ...
                app.calculatePCCandidateMapAgreement( ...
                finalResults);
            
            % =============================================================
            % 10. FINAL SELECTED K-MEANS SOLUTION
            % =============================================================
            
            labelsAll = ...
                double( ...
                selectedCandidate.MedoidLabelsAll);
            
            centroids = ...
                double( ...
                selectedCandidate.MedoidCentroids);
            
            nearestDistance = ...
                double( ...
                selectedCandidate.NearestDistance);
            
            confidence = ...
                double( ...
                selectedCandidate.AssignmentConfidence);
            
            selectedScores = ...
                allScoresMaximum(:, 1:selectedPC);
            
            % =============================================================
            % 11. STORE PCA MODEL
            %
            % PCAcoeff and AllPCAScores contain only selected PCs so all
            % existing downstream functions continue using the final model.
            % =============================================================
            
            app.PCAcoeff = ...
                coeffMaximum(:, 1:selectedPC);
            
            app.PCAmu = ...
                mu;
            
            app.PCAExplained = ...
                explained;
            
            app.NumPCUsed = ...
                selectedPC;
            
            app.AllPCAScores = ...
                selectedScores;
            
            app.MetricPixelIdx = ...
                pixelIdx;
            
            app.MetricLabels = ...
                labelsAll;
            
            app.ClusterCentroids = ...
                centroids;
            
            app.TrainingLocalIdx = ...
                trainingLocalIdx;
            
            app.TrainingLinearIdx = ...
                pixelIdx(trainingLocalIdx);
            
            app.PixelConfidence = ...
                confidence;
            
            % =============================================================
            % 12. STORE SELECTED STABILITY RESULT
            % =============================================================
            
            app.ClusterRunResults = ...
                selectedCandidate.RunResults;
            
            app.ClusterPairwiseARI = ...
                selectedCandidate.PairwiseARI;
            
            app.ClusterSelectedRun = ...
                selectedCandidate.SelectedRun;
            
            app.ClusterSelectedSeed = ...
                selectedCandidate.SelectedSeed;
            
            app.ClusterOverallMedianARI = ...
                selectedCandidate.OverallMedianARI;
            
            app.ClusterMedoidMedianARI = ...
                selectedCandidate.MedoidMedianARI;
            
            app.ClusterStabilityLevel = ...
                selectedCandidate.StabilityLevel;
            
            % =============================================================
            % 13. STORE COMPLETE PC-SELECTION RESULT
            % =============================================================
            
            app.PCASelectionResults = ...
                finalResults;
            
            app.PCASelectionSelectedIndex = ...
                selectedOriginalIndex;
            
            app.PCASelectionSelectedPC = ...
                selectedPC;
            
            app.PCASelectionReferenceIndex = ...
                selectedOriginalIndex;
            
            app.PCASelectionMapAgreement = ...
                mapAgreement;
            
            app.PCASelectionCompleted = ...
                true;
            
            % =============================================================
            % 14. BUILD FINAL LABEL AND CONFIDENCE MAPS
            % =============================================================
            
            [numberRows, numberColumns, numberWavenumbers] = ...
                size(app.RamanCube);
            
            labelMap = ...
                zeros(numberRows, numberColumns);
            
            labelMap(pixelIdx) = ...
                labelsAll;
            
            app.ClusterLabelMap = ...
                labelMap;
            
            confidenceMap = ...
                nan(numberRows, numberColumns);
            
            confidenceMap(pixelIdx) = ...
                confidence;
            
            app.PixelConfidenceMap = ...
                confidenceMap;
            
            % =============================================================
            % 15. BUILD CLUSTER OBJECTS
            % =============================================================
            
            app.Clusters = ...
                app.makeEmptyClusters();
            
            app.NextClusterID = ...
                1;
            
            clusterColors = ...
                app.makeInitialClusterColors(params.K);
            
            allRawSpectra = ...
                reshape( ...
                app.RamanCube, ...
                numberRows * numberColumns, ...
                numberWavenumbers);
            
            for clusterNumber = 1:params.K
                
                clusterPixels = ...
                    pixelIdx(labelsAll == clusterNumber);
                
                if isempty(clusterPixels)
                    continue
                end
                
                clusterSpectra = ...
                    allRawSpectra(clusterPixels, :);
                
                cluster = struct();
                
                cluster.ID = ...
                    app.NextClusterID;
                
                cluster.Name = ...
                    sprintf( ...
                    'Cluster %d', ...
                    cluster.ID);
                
                cluster.ParentID = ...
                    0;
                
                cluster.PixelIdx = ...
                    double(clusterPixels(:));
                
                cluster.Class = ...
                    'Unassigned';
                
                cluster.Active = ...
                    true;
                
                cluster.MeanSpectrum = ...
                    mean( ...
                    clusterSpectra, ...
                    1, ...
                    'omitnan');
                
                cluster.StdSpectrum = ...
                    std( ...
                    clusterSpectra, ...
                    0, ...
                    1, ...
                    'omitnan');
                
                cluster.Color = ...
                    clusterColors(clusterNumber, :);
                
                cluster.Metrics = ...
                    struct();
                
                app.Clusters(end + 1) = ...
                    cluster; %#ok<AGROW>
                
                app.NextClusterID = ...
                    app.NextClusterID + 1;
            end
            
            app.rebuildLabelMapFromClusters();
            
            % =============================================================
            % 16. COMPUTE FINAL CLUSTER METRICS
            % =============================================================
            
            app.computeAllClusterMetrics( ...
                nearestDistance);
            
            app.GlobalClusterMetrics.StabilityRuns = ...
                selectedCandidate.NumberRuns;
            
            app.GlobalClusterMetrics.SelectedRun = ...
                selectedCandidate.SelectedRun;
            
            app.GlobalClusterMetrics.SelectedSeed = ...
                selectedCandidate.SelectedSeed;
            
            app.GlobalClusterMetrics.OverallMedianARI = ...
                selectedCandidate.OverallMedianARI;
            
            app.GlobalClusterMetrics.MedoidMedianARI = ...
                selectedCandidate.MedoidMedianARI;
            
            app.GlobalClusterMetrics.StabilityLevel = ...
                selectedCandidate.StabilityLevel;
            
            app.GlobalClusterMetrics.AutomaticPCSelection = ...
                params.AutomaticPCSelection;
            
            app.GlobalClusterMetrics.SelectedPC = ...
                selectedPC;
            
            app.GlobalClusterMetrics.ExplainedVariance = ...
                selectedCandidate.ExplainedVariance;
            
            app.GlobalClusterMetrics.PCSelectionRule = ...
                selectionSummary.SelectionRule;
            
            app.GlobalClusterMetrics.PCSelectionFallback = ...
                selectionSummary.UsedFallback;
            
            % =============================================================
            % 17. STORE SETTINGS
            % =============================================================
            
            app.ClusterK = ...
                params.K;
            
            app.ClusterVarianceThreshold = ...
                params.VarianceThreshold;
            
            % ClusterMaxPC now represents the selected operational PC count,
            % not merely the maximum candidate model size.
            app.ClusterMaxPC = ...
                selectedPC;
            
            app.ClusterStabilityRuns = ...
                params.StabilityRuns;
            
            app.ClusterReplicates = ...
                params.Replicates;
            
            app.ClusterMaxIter = ...
                params.MaxIter;
            
            app.ClusterRandomSeed = ...
                params.RandomSeed;
            
            app.ClusterTrainingMaxSpectra = ...
                params.TrainingMaxSpectra;
            
            app.PCASelectionMaximumPC = ...
                maximumAvailablePC;
            
            app.PCASelectionFinalRuns = ...
                params.StabilityRuns;
            
            % =============================================================
            % 18. FINAL WORKFLOW STATE
            % =============================================================
            
            app.ClusterReady = ...
                true;
            
            app.ClusterConfirmed = ...
                false;
            
            app.UndoStack = {};
            app.RedoStack = {};
            
            app.SelectedClusterIDs = [];
            
            % Preserve the initial automatic segmentation so that all later
            % Split / Merge / Delete / Assign operations can be reset.
            app.InitialClusterSnapshot = ...
                app.captureCurrentClusterState();
            app.populateClusterTree();
            
            app.displayClusterMap();
            app.displayAllClusterMeans();
            
            app.setClusterControlsEnabled(true);
            
            app.UndoButton.Enable = ...
                'on';
            
            app.RedoButton.Enable = ...
                'on';
            
            % =============================================================
            % 19. SAVE COMPLETE EVIDENCE PACKAGE
            %
            % Evidence saving is intentionally separated from segmentation
            % success. A file-system failure must not discard a successfully
            % calculated clustering result.
            % =============================================================
            
            evidenceInfo = struct( ...
                'Completed', ...
                false, ...
                'RunFolder', ...
                '', ...
                'EvidenceMAT', ...
                '', ...
                'ModelMAT', ...
                '', ...
                'Warning', ...
                '');
            
            try
                
                evidenceInfo = ...
                    app.saveClusterSelectionEvidence( ...
                    params, ...
                    selectionSummary);
                
            catch evidenceError
                
                evidenceInfo.Completed = ...
                    false;
                
                evidenceInfo.Warning = ...
                    evidenceError.message;
            end
            
            % =============================================================
            % 20. CLOSE OLD REVIEW WINDOWS
            %
            % Review functions remain in the class for optional future use,
            % but automatic workflow no longer opens them.
            % =============================================================
            
            try
                
                if ~isempty(app.PCASelectionFigure) && ...
                        isvalid(app.PCASelectionFigure)
                    
                    delete(app.PCASelectionFigure);
                end
                
            catch
            end
            
            app.PCASelectionFigure = [];
            
            try
                
                if ~isempty(app.ClusterStabilityFigure) && ...
                        isvalid(app.ClusterStabilityFigure)
                    
                    delete(app.ClusterStabilityFigure);
                end
                
            catch
            end
            
            app.ClusterStabilityFigure = [];
            
            % =============================================================
            % 21. FINAL COMPACT REPORT
            % =============================================================
            
            if params.AutomaticPCSelection
                
                modeDescription = ...
                    'Automatic PC selection';
                
            else
                
                modeDescription = ...
                    'Fixed PC count';
            end
            
            reportLines = { ...
                'CLUSTER SEGMENTATION COMPLETED'; ...
                '================================================'; ...
                ''; ...
                ['Scan: ' app.CurrentScanName]; ...
                sprintf( ...
                'Valid spectra: %d', ...
                numberPixels); ...
                sprintf( ...
                'Training spectra: %d', ...
                numel(trainingLocalIdx)); ...
                sprintf( ...
                'Initial K: %d', ...
                params.K); ...
                ''; ...
                'SELECTED MODEL'; ...
                ['Mode: ' modeDescription]; ...
                sprintf( ...
                'Selected PCA components: %d', ...
                selectedPC); ...
                sprintf( ...
                'Explained variance retained: %.2f%%', ...
                selectedCandidate.ExplainedVariance); ...
                sprintf( ...
                'Overall median pairwise ARI: %.3f', ...
                selectedCandidate.OverallMedianARI); ...
                sprintf( ...
                'Selected medoid seed: %d', ...
                selectedCandidate.SelectedSeed); ...
                sprintf( ...
                'Medoid median ARI: %.3f', ...
                selectedCandidate.MedoidMedianARI); ...
                sprintf( ...
                'Stability level: %s', ...
                selectedCandidate.StabilityLevel); ...
                sprintf( ...
                'Median assignment confidence: %.3f', ...
                selectedCandidate.MedianConfidence); ...
                ''; ...
                'Axis 1 shows the selected final map.'; ...
                'Axis 3 shows active-cluster mean spectra.'};
            
            if evidenceInfo.Completed
                
                reportLines = ...
                    [ ...
                    reportLines; ...
                    { ...
                    ''; ...
                    'EVIDENCE PACKAGE'; ...
                    'All PC-selection and K-means evidence was saved.'; ...
                    ['Folder: ' evidenceInfo.RunFolder]}];
                
                app.GlobalClusterMetrics.EvidenceFolder = ...
                    evidenceInfo.RunFolder;
                
                app.GlobalClusterMetrics.EvidenceMAT = ...
                    evidenceInfo.EvidenceMAT;
                
                app.GlobalClusterMetrics.SelectedModelMAT = ...
                    evidenceInfo.ModelMAT;
                
            else
                
                reportLines = ...
                    [ ...
                    reportLines; ...
                    { ...
                    ''; ...
                    'EVIDENCE SAVE WARNING'; ...
                    ['Clustering completed, but evidence saving failed: ' ...
                    evidenceInfo.Warning]}];
                
                app.GlobalClusterMetrics.EvidenceFolder = ...
                    '';
                
                app.GlobalClusterMetrics.EvidenceSaveWarning = ...
                    evidenceInfo.Warning;
            end
            
            app.TextArea.Value = ...
                reportLines;
            
        end
        
        
        function textValue = yesNo(app, conditionValue) %#ok<INUSD>
            
            if conditionValue
                
                textValue = ...
                    'Yes';
                
            else
                
                textValue = ...
                    'No';
            end
            
        end
        
        
        function showClusterStabilityReview(app)
            
            % =============================================================
            % LIGHTWEIGHT STABILITY REVIEW FIGURE
            %
            % Default:
            %   representative maps only.
            %
            % Alternative:
            %   browse one run at a time.
            %
            % RGB images are not stored for each run. Label maps are created
            % only when rendered.
            % =============================================================
            
            if isempty(app.ClusterRunResults) || ...
                    isempty(app.MetricPixelIdx)
                
                return
            end
            
            try
                
                if ~isempty(app.ClusterStabilityFigure) && ...
                        isvalid(app.ClusterStabilityFigure)
                    
                    delete(app.ClusterStabilityFigure);
                end
                
            catch
            end
            
            reviewFigure = ...
                uifigure( ...
                'Name', ...
                'K-means stability review', ...
                'Position', ...
                [120 90 1120 720], ...
                'Color', ...
                [0.96 0.96 0.96]);
            
            app.ClusterStabilityFigure = ...
                reviewFigure;
            
            mainGrid = ...
                uigridlayout(reviewFigure);
            
            mainGrid.ColumnWidth = {'1x'};
            mainGrid.RowHeight = {45, '1x', 85};
            
            controlGrid = ...
                uigridlayout(mainGrid);
            
            controlGrid.Layout.Row = 1;
            controlGrid.Layout.Column = 1;
            
            controlGrid.ColumnWidth = { ...
                150, ...
                260, ...
                120, ...
                150, ...
                '1x'};
            
            controlGrid.RowHeight = {'1x'};
            
            modeLabel = ...
                uilabel( ...
                controlGrid, ...
                'Text', ...
                'Review mode:', ...
                'HorizontalAlignment', ...
                'right');
            
            modeLabel.Layout.Column = 1;
            
            modeDropDown = ...
                uidropdown( ...
                controlGrid, ...
                'Items', ...
                { ...
                'Representative maps', ...
                'Browse one-by-one'}, ...
                'Value', ...
                'Representative maps');
            
            modeDropDown.Layout.Column = 2;
            
            runLabel = ...
                uilabel( ...
                controlGrid, ...
                'Text', ...
                'Run:', ...
                'HorizontalAlignment', ...
                'right');
            
            runLabel.Layout.Column = 3;
            
            numberRuns = ...
                numel(app.ClusterRunResults);
            
            runItems = ...
                arrayfun( ...
                @(runNumber) ...
                sprintf( ...
                'Run %d | seed %d', ...
                runNumber, ...
                app.ClusterRunResults(runNumber).Seed), ...
                1:numberRuns, ...
                'UniformOutput', ...
                false);
            
            runDropDown = ...
                uidropdown( ...
                controlGrid, ...
                'Items', ...
                runItems, ...
                'Value', ...
                runItems{app.ClusterSelectedRun}, ...
                'Enable', ...
                'off');
            
            runDropDown.Layout.Column = 4;
            
            contentPanel = ...
                uipanel(mainGrid);
            
            contentPanel.Layout.Row = 2;
            contentPanel.Layout.Column = 1;
            
            statusArea = ...
                uitextarea( ...
                mainGrid, ...
                'Editable', ...
                'off');
            
            statusArea.Layout.Row = 3;
            statusArea.Layout.Column = 1;
            
            modeDropDown.ValueChangedFcn = ...
                @changeReviewMode;
            
            runDropDown.ValueChangedFcn = ...
                @changeDisplayedRun;
            
            displayRepresentativeMaps();
            
            % =============================================================
            % NESTED FUNCTIONS
            % =============================================================
            
            function changeReviewMode(~, ~)
                
                if strcmp( ...
                        modeDropDown.Value, ...
                        'Representative maps')
                    
                    runDropDown.Enable = ...
                        'off';
                    
                    displayRepresentativeMaps();
                    
                else
                    
                    runDropDown.Enable = ...
                        'on';
                    
                    displayOneRun( ...
                        runDropDown.Value);
                end
                
            end
            
            function changeDisplayedRun(~, ~)
                
                if strcmp( ...
                        modeDropDown.Value, ...
                        'Browse one-by-one')
                    
                    displayOneRun( ...
                        runDropDown.Value);
                end
                
            end
            
            function displayRepresentativeMaps()
                
                delete(contentPanel.Children);
                
                representativeRuns = ...
                    selectRepresentativeRuns();
                
                numberRepresentative = ...
                    numel(representativeRuns);
                
                numberColumns = ...
                    min(3, numberRepresentative);
                
                numberRows = ...
                    ceil( ...
                    numberRepresentative / ...
                    numberColumns);
                
                mapGrid = ...
                    uigridlayout(contentPanel);
                
                mapGrid.RowHeight = ...
                    repmat( ...
                    {'1x'}, ...
                    1, ...
                    numberRows);
                
                mapGrid.ColumnWidth = ...
                    repmat( ...
                    {'1x'}, ...
                    1, ...
                    numberColumns);
                
                for positionNumber = 1:numberRepresentative
                    
                    runNumber = ...
                        representativeRuns(positionNumber);
                    
                    currentAxis = ...
                        uiaxes(mapGrid);
                    
                    currentAxis.Layout.Row = ...
                        ceil(positionNumber / numberColumns);
                    
                    currentAxis.Layout.Column = ...
                        mod(positionNumber - 1, numberColumns) + 1;
                    
                    drawRunMap( ...
                        currentAxis, ...
                        runNumber);
                end
                
                statusArea.Value = { ...
                    'REPRESENTATIVE K-MEANS SOLUTIONS'; ...
                    '================================================'; ...
                    sprintf( ...
                    'Runs evaluated: %d', ...
                    numberRuns); ...
                    sprintf( ...
                    'Overall median pairwise ARI: %.3f', ...
                    app.ClusterOverallMedianARI); ...
                    sprintf( ...
                    'Selected medoid run: %d | seed=%d | median ARI=%.3f', ...
                    app.ClusterSelectedRun, ...
                    app.ClusterSelectedSeed, ...
                    app.ClusterMedoidMedianARI); ...
                    sprintf( ...
                    'Stability level: %s', ...
                    app.ClusterStabilityLevel); ...
                    ''; ...
                    ['Only representative maps are rendered. ' ...
                    'All run labels remain available in Browse one-by-one.']};
                
            end
            
            function displayOneRun(selectedText)
                
                delete(contentPanel.Children);
                
                selectedPosition = ...
                    find( ...
                    strcmp(runItems, selectedText), ...
                    1);
                
                if isempty(selectedPosition)
                    selectedPosition = app.ClusterSelectedRun;
                end
                
                singleGrid = ...
                    uigridlayout(contentPanel);
                
                singleGrid.RowHeight = {'1x'};
                singleGrid.ColumnWidth = {'1x'};
                
                currentAxis = ...
                    uiaxes(singleGrid);
                
                drawRunMap( ...
                    currentAxis, ...
                    selectedPosition);
                
                R = ...
                    app.ClusterRunResults(selectedPosition);
                
                statusArea.Value = { ...
                    'K-MEANS RUN REVIEW'; ...
                    '================================================'; ...
                    sprintf( ...
                    'Run: %d / %d', ...
                    selectedPosition, ...
                    numberRuns); ...
                    sprintf( ...
                    'Seed: %d', ...
                    R.Seed); ...
                    sprintf( ...
                    'Median ARI to all other runs: %.3f', ...
                    R.MedianARI); ...
                    sprintf( ...
                    'Training SSE: %.6g', ...
                    R.TotalSSE); ...
                    sprintf( ...
                    'Selected medoid: %s', ...
                    app.yesNo( ...
                    selectedPosition == ...
                    app.ClusterSelectedRun))};
                
            end
            
            function representativeRuns = selectRepresentativeRuns()
                
                medianARIValues = ...
                    [app.ClusterRunResults.MedianARI];
                
                sseValues = ...
                    [app.ClusterRunResults.TotalSSE];
                
                selectedRun = ...
                    app.ClusterSelectedRun;
                
                [~, lowestSSERun] = ...
                    min(sseValues);
                
                similarityToMedoid = ...
                    app.ClusterPairwiseARI(selectedRun, :);
                
                similarityToMedoid(selectedRun) = ...
                    -Inf;
                
                [~, closestAlternative] = ...
                    max(similarityToMedoid);
                
                similarityToMedoid(selectedRun) = ...
                    Inf;
                
                [~, leastSimilarRun] = ...
                    min(similarityToMedoid);
                
                [~, lowestMedianRun] = ...
                    min(medianARIValues);
                
                representativeRuns = ...
                    unique( ...
                    [ ...
                    selectedRun, ...
                    lowestSSERun, ...
                    closestAlternative, ...
                    leastSimilarRun, ...
                    lowestMedianRun], ...
                    'stable');
                
                representativeRuns = ...
                    representativeRuns( ...
                    isfinite(representativeRuns) & ...
                    representativeRuns >= 1 & ...
                    representativeRuns <= numberRuns);
                
                representativeRuns = ...
                    representativeRuns( ...
                    1:min(5, numel(representativeRuns)));
                
            end
            
            function drawRunMap(targetAxis, runNumber)
                
                currentLabels = ...
                    double( ...
                    app.ClusterRunResults(runNumber).LabelsAll);
                
                labelMap = ...
                    zeros(size(app.ValidMask2D));
                
                labelMap(app.MetricPixelIdx) = ...
                    currentLabels;
                
                imagesc( ...
                    targetAxis, ...
                    labelMap);
                
                axis(targetAxis, 'image');
                
                targetAxis.YDir = ...
                    'normal';
                
                targetAxis.XTick = [];
                targetAxis.YTick = [];
                
                colormap( ...
                    targetAxis, ...
                    lines(max(2, app.ClusterK)));
                
                clim( ...
                    targetAxis, ...
                    [0.5 app.ClusterK + 0.5]);
                
                R = ...
                    app.ClusterRunResults(runNumber);
                
                if runNumber == app.ClusterSelectedRun
                    
                    runRole = ...
                        'SELECTED MEDOID';
                    
                elseif R.TotalSSE == ...
                        min([app.ClusterRunResults.TotalSSE])
                    
                    runRole = ...
                        'LOWEST SSE';
                    
                else
                    
                    runRole = ...
                        'ALTERNATIVE';
                end
                
                title( ...
                    targetAxis, ...
                    { ...
                    sprintf( ...
                    'Run %d | seed %d', ...
                    runNumber, ...
                    R.Seed), ...
                    sprintf( ...
                    'Median ARI %.3f | SSE %.4g', ...
                    R.MedianARI, ...
                    R.TotalSSE), ...
                    runRole}, ...
                    'Interpreter', ...
                    'none');
                
            end
            
        end

        function params = requestClusterParameters(app)
            
            % =============================================================
            % PCA-DIMENSION SELECTION + MULTI-SEED K-MEANS PARAMETERS
            %
            % Automatic mode:
            %   - PCA is fitted once up to Maximum PCA model components.
            %   - Candidate nested PC spaces are screened.
            %   - Top candidates are validated with more seeds.
            %
            % Fixed mode:
            %   - Behaves like the previous workflow.
            %   - Uses one user-specified PC count.
            % =============================================================
            
            if app.PCASelectionEnabled
                
                defaultMode = ...
                    'Automatic PC selection';
                
            else
                
                defaultMode = ...
                    'Fixed PC count';
            end
            
            defaults = { ...
                num2str(app.ClusterK), ...
                defaultMode, ...
                app.PCASelectionCandidateText, ...
                num2str(app.PCASelectionMaximumPC), ...
                num2str(app.PCASelectionCoarseRuns), ...
                num2str(app.PCASelectionFinalCandidateCount), ...
                num2str(app.ClusterStabilityRuns), ...
                num2str(max(1, min(2, app.ClusterReplicates))), ...
                num2str(app.ClusterMaxIter), ...
                num2str(app.ClusterRandomSeed), ...
                num2str(app.ClusterTrainingMaxSpectra)};
            
            prompts = { ...
                'Number of initial clusters, K', ...
                ['PC selection mode: enter exactly ' ...
                '"Automatic PC selection" or "Fixed PC count"'], ...
                ['PC candidates for automatic mode ' ...
                '(example: 5 10 15 20 25 30 40 50 75 100)'], ...
                ['Maximum PCA model components. In fixed mode, ' ...
                'this is the fixed number of PCs'], ...
                'Coarse K-means runs per PC candidate', ...
                'Number of top PC candidates for final validation', ...
                'Final stability runs per selected PC candidate', ...
                'Internal replicates per K-means run', ...
                'K-means maximum iterations', ...
                'Base random seed', ...
                'Maximum training spectra'};
            
            answer = ...
                inputdlg( ...
                prompts, ...
                'Automatic PCA dimension + K-means stability', ...
                [ ...
                1 82; ...
                1 82; ...
                1 82; ...
                1 82; ...
                1 82; ...
                1 82; ...
                1 82; ...
                1 82; ...
                1 82; ...
                1 82; ...
                1 82], ...
                defaults);
            
            if isempty(answer)
                
                params = [];
                return
            end
            
            % =============================================================
            % 1. READ GENERAL PARAMETERS
            % =============================================================
            
            params = struct();
            
            params.K = ...
                round(str2double(answer{1}));
            
            modeText = ...
                strtrim( ...
                char(string(answer{2})));
            
            candidateText = ...
                strtrim( ...
                char(string(answer{3})));
            
            params.MaxPC = ...
                round(str2double(answer{4}));
            
            params.CoarseRuns = ...
                round(str2double(answer{5}));
            
            params.FinalCandidateCount = ...
                round(str2double(answer{6}));
            
            params.StabilityRuns = ...
                round(str2double(answer{7}));
            
            params.Replicates = ...
                round(str2double(answer{8}));
            
            params.MaxIter = ...
                round(str2double(answer{9}));
            
            params.RandomSeed = ...
                round(str2double(answer{10}));
            
            params.TrainingMaxSpectra = ...
                round(str2double(answer{11}));
            
            % The previous field is retained for compatibility with any
            % existing code that still reads VarianceThreshold.
            params.VarianceThreshold = ...
                app.ClusterVarianceThreshold;
            
            % =============================================================
            % 2. INTERPRET PC-SELECTION MODE
            % =============================================================
            
            if strcmpi( ...
                    modeText, ...
                    'Automatic PC selection')
                
                params.AutomaticPCSelection = ...
                    true;
                
            elseif strcmpi( ...
                    modeText, ...
                    'Fixed PC count')
                
                params.AutomaticPCSelection = ...
                    false;
                
            else
                
                uialert( ...
                    app.UIFigure, ...
                    { ...
                    'PC selection mode was not recognized.'; ...
                    ''; ...
                    'Enter exactly one of:'; ...
                    'Automatic PC selection'; ...
                    'Fixed PC count'}, ...
                    'Invalid PC selection mode');
                
                params = [];
                return
            end
            
            % =============================================================
            % 3. PARSE PC CANDIDATES
            %
            % Accept separators:
            %   spaces
            %   commas
            %   semicolons
            % =============================================================
            
            normalizedCandidateText = ...
                regexprep( ...
                candidateText, ...
                '[,;]+', ...
                ' ');
            
            candidateTokens = ...
                regexp( ...
                strtrim(normalizedCandidateText), ...
                '\s+', ...
                'split');
            
            candidateValues = ...
                nan(size(candidateTokens));
            
            for tokenNumber = 1:numel(candidateTokens)
                
                candidateValues(tokenNumber) = ...
                    str2double(candidateTokens{tokenNumber});
            end
            
            candidateValues = ...
                candidateValues( ...
                isfinite(candidateValues));
            
            candidateValues = ...
                round(candidateValues);
            
            candidateValues = ...
                candidateValues(candidateValues >= 1);
            
            candidateValues = ...
                unique( ...
                candidateValues, ...
                'sorted');
            
            if params.AutomaticPCSelection && ...
                    isempty(candidateValues)
                
                uialert( ...
                    app.UIFigure, ...
                    ['Automatic PC selection requires at least one ' ...
                    'valid positive integer candidate.'], ...
                    'Invalid PC candidates');
                
                params = [];
                return
            end
            
            % =============================================================
            % 4. VALIDATE NUMERIC INPUT
            % =============================================================
            
            generalParameterVector = [ ...
                params.K, ...
                params.MaxPC, ...
                params.CoarseRuns, ...
                params.FinalCandidateCount, ...
                params.StabilityRuns, ...
                params.Replicates, ...
                params.MaxIter, ...
                params.RandomSeed, ...
                params.TrainingMaxSpectra];
            
            if any(~isfinite(generalParameterVector))
                
                uialert( ...
                    app.UIFigure, ...
                    'One or more clustering parameters are invalid.', ...
                    'Invalid parameters');
                
                params = [];
                return
            end
            
            % =============================================================
            % 5. APPLY SAFE LIMITS
            % =============================================================
            
            params.K = ...
                max(2, params.K);
            
            params.MaxPC = ...
                max(1, params.MaxPC);
            
            % Coarse stage needs enough seeds to identify obviously unstable
            % candidate spaces, but remains intentionally lightweight.
            params.CoarseRuns = ...
                min(20, max(5, params.CoarseRuns));
            
            params.StabilityRuns = ...
                min(50, max(10, params.StabilityRuns));
            
            params.Replicates = ...
                min(5, max(1, params.Replicates));
            
            params.MaxIter = ...
                max(50, params.MaxIter);
            
            params.TrainingMaxSpectra = ...
                max(100, params.TrainingMaxSpectra);
            
            % =============================================================
            % 6. FINALIZE CANDIDATES
            % =============================================================
            
            if params.AutomaticPCSelection
                
                % Candidate spaces cannot exceed the maximum PCA model size.
                candidateValues = ...
                    candidateValues( ...
                    candidateValues <= params.MaxPC);
                
                % Guarantee that the maximum permitted dimension is tested,
                % unless it already appears in the candidate list.
                candidateValues = ...
                    unique( ...
                    [candidateValues(:); params.MaxPC], ...
                    'sorted');
                
                if numel(candidateValues) < 2
                    
                    uialert( ...
                        app.UIFigure, ...
                        { ...
                        ['Automatic selection requires at least two distinct ' ...
                        'PC candidates after applying Maximum PCA components.']; ...
                        ''; ...
                        ['Increase Maximum PCA components or provide a wider ' ...
                        'candidate list.']}, ...
                        'Too few PC candidates');
                    
                    params = [];
                    return
                end
                
                params.FinalCandidateCount = ...
                    min( ...
                    max(1, params.FinalCandidateCount), ...
                    numel(candidateValues));
                
            else
                
                % Fixed mode evaluates exactly one PCA dimension.
                candidateValues = ...
                    params.MaxPC;
                
                params.CoarseRuns = ...
                    params.StabilityRuns;
                
                params.FinalCandidateCount = ...
                    1;
            end
            
            params.PCCandidates = ...
                double(candidateValues(:).');
            
            params.CandidateText = ...
                strtrim(candidateText);
            
            % =============================================================
            % 7. STORE CURRENT USER PREFERENCES
            % =============================================================
            
            app.PCASelectionEnabled = ...
                params.AutomaticPCSelection;
            
            app.PCASelectionCandidateText = ...
                params.CandidateText;
            
            app.PCASelectionCandidates = ...
                params.PCCandidates;
            
            app.PCASelectionMaximumPC = ...
                params.MaxPC;
            
            app.PCASelectionCoarseRuns = ...
                params.CoarseRuns;
            
            app.PCASelectionFinalCandidateCount = ...
                params.FinalCandidateCount;
            
            app.PCASelectionFinalRuns = ...
                params.StabilityRuns;
            
        end
        
        
        function colors = makeInitialClusterColors(app, nClusters)
            
            selectedPalette = ...
                strtrim( ...
                char(string(app.MapcolorsDropDown.Value)));
            
            if isempty(selectedPalette)
                
                selectedPalette = ...
                    'Default';
            end
            
            colors = ...
                app.makePaletteColors( ...
                selectedPalette, ...
                nClusters);
            
        end
        
        
        function bandMap = buildSingleExtractBandMap(app, cube, wn)
            
            wn = ...
                double(wn(:));
            
            bandMask = ...
                wn >= app.SingleExtractBandRange(1) & ...
                wn <= app.SingleExtractBandRange(2);
            
            if nnz(bandMask) < 2
                
                error( ...
                    'single_extract:BandUnavailable', ...
                    sprintf([ ...
                    'The READABLE Raman axis does not contain enough points ' ...
                    'inside %.0f-%.0f cm^-1.'], ...
                    app.SingleExtractBandRange(1), ...
                    app.SingleExtractBandRange(2)));
            end
            
            bandMap = ...
                mean( ...
                double(cube(:, :, bandMask)), ...
                3, ...
                'omitnan');
            
            bandMap = ...
                double(bandMap);
            
        end
        
        
        function namePart = getLastPathPart(app, pathValue) %#ok<INUSD>
            
            [~, namePart] = ...
                fileparts( ...
                char(string(pathValue)));
            
        end
        
        
        function populateSingleExtractTree(app)
            
            try
                delete(app.Tree2.Children);
            catch
            end
            
            if isempty(app.SingleExtractSamples)
                
                uitreenode( ...
                    app.Tree2, ...
                    'Text', ...
                    'No cluster package loaded', ...
                    'NodeData', ...
                    struct('Type', 'root'));
                
                return
            end
            
            for sampleNumber = 1:numel(app.SingleExtractSamples)
                
                S = ...
                    app.SingleExtractSamples(sampleNumber);
                
                switch char(app.ListmodeDropDown.Value)
                    
                    case 'Pixel count'
                        
                        nodeText = ...
                            sprintf( ...
                            '%s | pixels=%d', ...
                            S.Name, ...
                            S.PixelCount);
                        
                    otherwise
                        
                        nodeText = ...
                            S.Name;
                end
                
                uitreenode( ...
                    app.Tree2, ...
                    'Text', ...
                    nodeText, ...
                    'NodeData', ...
                    struct( ...
                    'Type', ...
                    'sample', ...
                    'SampleIndex', ...
                    sampleNumber, ...
                    'SampleName', ...
                    S.Name));
            end
            
        end
        
        
        function status = classifyRepresentativeSpatialStatus( ...
                app, ...
                pixelCount, ...
                targetPixels)
            
            pixelCount = ...
                round(double(pixelCount));
            
            targetPixels = ...
                max(1, round(double(targetPixels)));
            
            if pixelCount < ...
                    app.SingleExtractMinimumPixelsPerMean
                
                status = ...
                    "Singleton";
                
                return
            end
            
            lowerBound = ...
                (1 - app.SingleExtractSupportToleranceFraction) * ...
                targetPixels;
            
            upperBound = ...
                (1 + app.SingleExtractSupportToleranceFraction) * ...
                targetPixels;
            
            if pixelCount < lowerBound
                
                status = ...
                    "Undersized";
                
            elseif pixelCount > upperBound
                
                status = ...
                    "Oversized";
                
            else
                
                status = ...
                    "Normal";
            end
            
        end
        
        
        function partition = buildBalancedSpatialPartition( ...
                app, ...
                clusterMask, ...
                targetPixels)
            
            clusterMask = ...
                logical(clusterMask);
            
            if ~any(clusterMask(:))
                
                error( ...
                    'single_extract:EmptyClusterMask', ...
                    'Cluster mask contains no pixel.');
            end
            
            targetPixels = ...
                max( ...
                app.SingleExtractMinimumPixelsPerMean, ...
                round(targetPixels));
            
            % =============================================================
            % 1. ORIGINAL CONNECTED ISLANDS
            % =============================================================
            
            sourceCC = ...
                bwconncomp( ...
                clusterMask, ...
                app.SingleExtractSpatialConnectivity);
            
            groups = {};
            islandIDs = [];
            spatialStatus = strings(0,1);
            componentCounts = [];
            
            % =============================================================
            % 2. PROCESS EACH ORIGINAL ISLAND INDEPENDENTLY
            %
            % A representative never combines disconnected source islands.
            % =============================================================
            
            for islandNumber = 1:sourceCC.NumObjects
                
                islandPixels = ...
                    double( ...
                    sourceCC.PixelIdxList{islandNumber}(:));
                
                if isempty(islandPixels)
                    continue
                end
                
                islandMask = ...
                    false(size(clusterMask));
                
                islandMask(islandPixels) = ...
                    true;
                
                islandGroups = ...
                    app.partitionOneConnectedIsland( ...
                    islandMask, ...
                    targetPixels);
                
                for localGroupNumber = 1:numel(islandGroups)
                    
                    currentPixels = ...
                        double( ...
                        islandGroups{localGroupNumber}(:));
                    
                    currentMask = ...
                        false(size(clusterMask));
                    
                    currentMask(currentPixels) = ...
                        true;
                    
                    currentCC = ...
                        bwconncomp( ...
                        currentMask, ...
                        app.SingleExtractSpatialConnectivity);
                    
                    if currentCC.NumObjects ~= 1
                        
                        error( ...
                            'single_extract:DisconnectedRepresentative', ...
                            sprintf([ ...
                            'Spatial representative %d from source island %d ' ...
                            'is not connected.'], ...
                            localGroupNumber, ...
                            islandNumber));
                    end
                    
                    groups{end + 1, 1} = ...
                        currentPixels; %#ok<AGROW>
                    
                    islandIDs(end + 1, 1) = ...
                        islandNumber; %#ok<AGROW>
                    
                    componentCounts(end + 1, 1) = ...
                        currentCC.NumObjects; %#ok<AGROW>
                    
                    spatialStatus(end + 1, 1) = ...
                        app.classifyRepresentativeSpatialStatus( ...
                        numel(currentPixels), ...
                        targetPixels); %#ok<AGROW>
                end
            end
            
            if isempty(groups)
                
                error( ...
                    'single_extract:NoSpatialRepresentative', ...
                    'No spatial representative could be generated.');
            end
            
            % =============================================================
            % 3. COMPLETE PIXEL-CONSERVATION CHECK
            % =============================================================
            
            allPartitionPixels = ...
                vertcat(groups{:});
            
            originalPixels = ...
                double(find(clusterMask));
            
            if numel(allPartitionPixels) ~= ...
                    numel(originalPixels)
                
                error( ...
                    'single_extract:SpatialPartitionCountMismatch', ...
                    sprintf([ ...
                    'Input pixels: %d\n' ...
                    'Partition pixels: %d'], ...
                    numel(originalPixels), ...
                    numel(allPartitionPixels)));
            end
            
            if numel(unique(allPartitionPixels)) ~= ...
                    numel(allPartitionPixels)
                
                error( ...
                    'single_extract:SpatialPartitionOverlap', ...
                    'One or more source pixels were assigned to multiple representatives.');
            end
            
            if ~isequal( ...
                    sort(allPartitionPixels(:)), ...
                    sort(originalPixels(:)))
                
                error( ...
                    'single_extract:SpatialPartitionIntegrity', ...
                    ['Spatial partition does not preserve every source pixel ' ...
                    'exactly once.']);
            end
            
            % =============================================================
            % 4. DESCRIPTIVE INFORMATION
            % =============================================================
            
            numberGroups = ...
                numel(groups);
            
            groupSizes = ...
                zeros(numberGroups, 1);
            
            centroidRow = ...
                nan(numberGroups, 1);
            
            centroidColumn = ...
                nan(numberGroups, 1);
            
            for groupNumber = 1:numberGroups
                
                currentPixels = ...
                    groups{groupNumber};
                
                groupSizes(groupNumber) = ...
                    numel(currentPixels);
                
                [rr, cc] = ...
                    ind2sub( ...
                    size(clusterMask), ...
                    currentPixels);
                
                centroidRow(groupNumber) = ...
                    mean(double(rr), 'omitnan');
                
                centroidColumn(groupNumber) = ...
                    mean(double(cc), 'omitnan');
            end
            
            % =============================================================
            % 5. OUTPUT
            % =============================================================
            
            partition = struct();
            
            partition.Groups = ...
                groups;
            
            partition.IslandID = ...
                islandIDs;
            
            partition.GroupSizes = ...
                groupSizes;
            
            partition.CentroidRow = ...
                centroidRow;
            
            partition.CentroidColumn = ...
                centroidColumn;
            
            partition.ConnectedComponentCount = ...
                componentCounts;
            
            partition.SpatialStatus = ...
                spatialStatus;
            
            partition.TargetPixelsPerMean = ...
                targetPixels;
            
            partition.MinimumGroupSize = ...
                min(groupSizes);
            
            partition.MaximumGroupSize = ...
                max(groupSizes);
            
            partition.MedianGroupSize = ...
                median(groupSizes);
            
            partition.NumberRepresentatives = ...
                numberGroups;
            
            partition.SourceIslandCount = ...
                sourceCC.NumObjects;
            
            partition.InputPixelCount = ...
                numel(originalPixels);
            
            partition.RepresentedPixelCount = ...
                numel(allPartitionPixels);
            
            partition.AllPixelsPreserved = ...
                true;
            
            partition.AllRepresentativesConnected = ...
                all(componentCounts == 1);
            
        end
        
        
        function groups = partitionOneConnectedIsland( ...
                app, ...
                islandMask, ...
                targetPixels)
            
            islandMask = ...
                logical(islandMask);
            
            groups = {};
            
            remainingMask = ...
                islandMask;
            
            while any(remainingMask(:))
                
                % =============================================================
                % Remaining mask may split after peeling one patch.
                % Always process one connected component at a time.
                % =============================================================
                
                CC = ...
                    bwconncomp( ...
                    remainingMask, ...
                    app.SingleExtractSpatialConnectivity);
                
                componentSizes = ...
                    cellfun( ...
                    @numel, ...
                    CC.PixelIdxList);
                
                % Largest component first.
                % Tie is deterministic because max returns first occurrence.
                [numberPixels, componentIndex] = ...
                    max(componentSizes);
                
                componentPixels = ...
                    double( ...
                    CC.PixelIdxList{componentIndex}(:));
                
                if numberPixels <= targetPixels
                    
                    groups{end + 1, 1} = ...
                        componentPixels; %#ok<AGROW>
                    
                    remainingMask(componentPixels) = ...
                        false;
                    
                    continue
                end
                
                % =============================================================
                % BALANCED DESIRED SIZE
                %
                % Rather than always removing exactly targetPixels and leaving
                % a tiny remainder, estimate how many representatives the
                % current component should contain and divide support evenly.
                % =============================================================
                
                expectedGroups = ...
                    max( ...
                    1, ...
                    round(numberPixels / targetPixels));
                
                desiredSize = ...
                    round( ...
                    numberPixels / expectedGroups);
                
                desiredSize = ...
                    max( ...
                    app.SingleExtractMinimumPixelsPerMean, ...
                    desiredSize);
                
                desiredSize = ...
                    min( ...
                    desiredSize, ...
                    numberPixels);
                
                % =============================================================
                % CHOOSE DETERMINISTIC BOUNDARY-LIKE SEED
                %
                % Start from the pixel farthest from the component centroid.
                % This tends to peel from the exterior rather than cutting
                % straight through the centre of the spatial region.
                % =============================================================
                
                [row, column] = ...
                    ind2sub( ...
                    size(remainingMask), ...
                    componentPixels);
                
                row = ...
                    double(row(:));
                
                column = ...
                    double(column(:));
                
                centerRow = ...
                    mean(row);
                
                centerColumn = ...
                    mean(column);
                
                squaredDistance = ...
                    (row - centerRow).^2 + ...
                    (column - centerColumn).^2;
                
                maximumDistance = ...
                    max(squaredDistance);
                
                seedCandidates = ...
                    componentPixels( ...
                    squaredDistance == maximumDistance);
                
                seedPixel = ...
                    min(seedCandidates);
                
                % =============================================================
                % EXTRACT ONE CONNECTED REGION BY DETERMINISTIC BFS
                % =============================================================
                
                currentRegion = ...
                    app.extractConnectedRegionBFS( ...
                    componentPixels, ...
                    size(remainingMask), ...
                    seedPixel, ...
                    desiredSize);
                
                if isempty(currentRegion)
                    
                    error( ...
                        'single_extract:ConnectedRegionFailure', ...
                        'Connected spatial region growing returned an empty region.');
                end
                
                groups{end + 1, 1} = ...
                    currentRegion(:); %#ok<AGROW>
                
                remainingMask(currentRegion) = ...
                    false;
            end
            
        end


        function regionPixels = extractConnectedRegionBFS( ...
                app, ...
                allowedPixels, ...
                mapSize, ...
                seedPixel, ...
                desiredCount)
            
            allowedPixels = ...
                double(allowedPixels(:));
            
            desiredCount = ...
                min( ...
                round(desiredCount), ...
                numel(allowedPixels));
            
            allowedMask = ...
                false(mapSize);
            
            allowedMask(allowedPixels) = ...
                true;
            
            visitedMask = ...
                false(mapSize);
            
            queue = ...
                zeros(numel(allowedPixels), 1);
            
            queueStart = ...
                1;
            
            queueEnd = ...
                1;
            
            queue(1) = ...
                seedPixel;
            
            visitedMask(seedPixel) = ...
                true;
            
            regionPixels = ...
                zeros(desiredCount, 1);
            
            regionCount = ...
                0;
            
            numberRows = ...
                mapSize(1);
            
            numberColumns = ...
                mapSize(2);
            
            if app.SingleExtractSpatialConnectivity == 8
                
                offsets = [ ...
                    -1 -1
                    -1  0
                    -1  1
                    0 -1
                    0  1
                    1 -1
                    1  0
                    1  1];
                
            else
                
                offsets = [ ...
                    -1  0
                    0 -1
                    0  1
                    1  0];
            end
            
            while queueStart <= queueEnd && ...
                    regionCount < desiredCount
                
                currentPixel = ...
                    queue(queueStart);
                
                queueStart = ...
                    queueStart + 1;
                
                regionCount = ...
                    regionCount + 1;
                
                regionPixels(regionCount) = ...
                    currentPixel;
                
                [currentRow, currentColumn] = ...
                    ind2sub( ...
                    mapSize, ...
                    currentPixel);
                
                neighborPixels = [];
                
                for offsetNumber = 1:size(offsets, 1)
                    
                    rr = ...
                        currentRow + ...
                        offsets(offsetNumber, 1);
                    
                    cc = ...
                        currentColumn + ...
                        offsets(offsetNumber, 2);
                    
                    if rr < 1 || ...
                            rr > numberRows || ...
                            cc < 1 || ...
                            cc > numberColumns
                        
                        continue
                    end
                    
                    neighborPixel = ...
                        sub2ind( ...
                        mapSize, ...
                        rr, ...
                        cc);
                    
                    if allowedMask(neighborPixel) && ...
                            ~visitedMask(neighborPixel)
                        
                        neighborPixels(end + 1, 1) = ...
                            neighborPixel; %#ok<AGROW>
                    end
                end
                
                % Deterministic neighbour order.
                if ~isempty(neighborPixels)
                    
                    neighborPixels = ...
                        sort(neighborPixels);
                    
                    for neighborNumber = 1:numel(neighborPixels)
                        
                        neighborPixel = ...
                            neighborPixels(neighborNumber);
                        
                        if visitedMask(neighborPixel)
                            continue
                        end
                        
                        visitedMask(neighborPixel) = ...
                            true;
                        
                        queueEnd = ...
                            queueEnd + 1;
                        
                        queue(queueEnd) = ...
                            neighborPixel;
                    end
                end
            end
            
            regionPixels = ...
                regionPixels(1:regionCount);
            
            % =============================================================
            % CONNECTIVITY ASSERTION
            % =============================================================
            
            regionMask = ...
                false(mapSize);
            
            regionMask(regionPixels) = ...
                true;
            
            CC = ...
                bwconncomp( ...
                regionMask, ...
                app.SingleExtractSpatialConnectivity);
            
            if CC.NumObjects ~= 1
                
                error( ...
                    'single_extract:BFSConnectivityFailure', ...
                    'BFS produced a disconnected spatial representative.');
            end
            
        end
        
        
        function displaySelectedSingleExtractSample(app)
            
            sampleIndex = ...
                app.SingleExtractSelectedSampleIndex;
            
            if ~isfinite(sampleIndex) || ...
                    sampleIndex < 1 || ...
                    sampleIndex > numel(app.SingleExtractSamples)
                
                cla(app.UIAxes2, 'reset');
                
                title( ...
                    app.UIAxes2, ...
                    'Select one loaded sample');
                
                return
            end
            
            S = ...
                app.SingleExtractSamples(sampleIndex);
            
            modeValue = ...
                lower( ...
                strtrim( ...
                char(string(app.MaptypeDropDown.Value))));
            
            switch modeValue
                
                case {'band', 'band map'}
                    
                    app.displaySingleExtractBandMap(S);
                    
                case {'spectral', 'spectral map'}
                    
                    app.displaySingleExtractSpectra(S);
                    
                case {'masked band', 'masked band map'}
                    
                    app.displaySingleExtractMaskedBand(S);
                    
                otherwise
                    
                    app.displaySingleExtractMaskedBand(S);
            end
            
            drawnow;
            
        end
        
        function displaySingleExtractBandMap(app, S)
            
            bandMap = ...
                double(S.BandMap);
            
            cla(app.UIAxes2, 'reset');
            
            imagesc( ...
                app.UIAxes2, ...
                bandMap);
            
            axis( ...
                app.UIAxes2, ...
                'image');
            
            app.UIAxes2.YDir = ...
                'normal';
            
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            
            colormap( ...
                app.UIAxes2, ...
                hot(256));
            
            values = ...
                bandMap(isfinite(bandMap));
            
            if ~isempty(values)
                
                lowValue = ...
                    prctile(values, 1);
                
                highValue = ...
                    prctile(values, 99);
                
                if isfinite(lowValue) && ...
                        isfinite(highValue) && ...
                        lowValue < highValue
                    
                    clim( ...
                        app.UIAxes2, ...
                        [lowValue highValue]);
                end
            end
            
            title( ...
                app.UIAxes2, ...
                { ...
                S.Name, ...
                sprintf( ...
                'Raman band %.0f-%.0f cm^{-1}', ...
                app.SingleExtractBandRange(1), ...
                app.SingleExtractBandRange(2))}, ...
                'Interpreter', ...
                'none');
            
        end
        
        function displaySingleExtractMaskedBand(app, S)
            
            bandMap = ...
                double(S.BandMap);
            
            clusterMask = ...
                logical( ...
                S.ClusterPackage.Map.ClusterMask2D);
            
            cla(app.UIAxes2, 'reset');
            
            baseHandle = ...
                imagesc( ...
                app.UIAxes2, ...
                bandMap);
            
            baseHandle.HitTest = ...
                'off';
            
            axis( ...
                app.UIAxes2, ...
                'image');
            
            app.UIAxes2.YDir = ...
                'normal';
            
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            
            % Grayscale base for colored mask.
            colormap( ...
                app.UIAxes2, ...
                gray(256));
            
            values = ...
                bandMap(isfinite(bandMap));
            
            if ~isempty(values)
                
                lowValue = ...
                    prctile(values, 1);
                
                highValue = ...
                    prctile(values, 99);
                
                if isfinite(lowValue) && ...
                        isfinite(highValue) && ...
                        lowValue < highValue
                    
                    clim( ...
                        app.UIAxes2, ...
                        [lowValue highValue]);
                end
            end
            
            hold( ...
                app.UIAxes2, ...
                'on');
            
            % =============================================================
            % TRANSPARENT CLUSTER OVERLAY
            % =============================================================
            
            overlayRGB = ...
                zeros( ...
                [size(clusterMask) 3]);
            
            % Fixed warm overlay.
            overlayRGB(:, :, 1) = ...
                double(clusterMask);
            
            overlayRGB(:, :, 2) = ...
                0.15 .* double(clusterMask);
            
            overlayRGB(:, :, 3) = ...
                0.05 .* double(clusterMask);
            
            overlayHandle = ...
                image( ...
                app.UIAxes2, ...
                overlayRGB);
            
            overlayHandle.AlphaData = ...
                0.42 .* double(clusterMask);
            
            overlayHandle.AlphaDataMapping = ...
                'none';
            
            overlayHandle.HitTest = ...
                'off';
            
            contour( ...
                app.UIAxes2, ...
                clusterMask, ...
                [0.5 0.5], ...
                'w', ...
                'LineWidth', ...
                1.2, ...
                'HitTest', ...
                'off');
            
            hold( ...
                app.UIAxes2, ...
                'off');
            
            title( ...
                app.UIAxes2, ...
                { ...
                S.Name, ...
                sprintf( ...
                'Band %.0f-%.0f cm^{-1} + selected cluster | pixels=%d', ...
                app.SingleExtractBandRange(1), ...
                app.SingleExtractBandRange(2), ...
                S.PixelCount)}, ...
                'Interpreter', ...
                'none');
            
        end
        
        
        function displaySingleExtractSpectra(app, S)
            
            wn = ...
                double( ...
                S.ClusterPackage.Wavenumber(:));
            
            spectra = ...
                double( ...
                S.ClusterPackage.Spectra.Pretreated);
            
            if isempty(spectra)
                
                error( ...
                    'single_extract:EmptyPretreatedSpectra', ...
                    sprintf( ...
                    'Sample "%s" contains no pretreated spectra.', ...
                    S.Name));
            end
            
            if size(spectra, 2) ~= numel(wn)
                
                error( ...
                    'single_extract:PretreatedDimensionMismatch', ...
                    sprintf( ...
                    'Pretreated spectra and Wavenumber are not aligned for "%s".', ...
                    S.Name));
            end
            
            cla(app.UIAxes2, 'reset');
            
            hold( ...
                app.UIAxes2, ...
                'on');
            
            % Display at most 300 individual spectra for responsiveness.
            numberSpectra = ...
                size(spectra, 1);
            
            maximumDisplay = ...
                min( ...
                300, ...
                numberSpectra);
            
            if numberSpectra <= maximumDisplay
                
                displayRows = ...
                    1:numberSpectra;
                
            else
                
                displayRows = ...
                    unique( ...
                    round( ...
                    linspace( ...
                    1, ...
                    numberSpectra, ...
                    maximumDisplay)));
            end
            
            for rowNumber = displayRows
                
                plot( ...
                    app.UIAxes2, ...
                    wn, ...
                    spectra(rowNumber, :), ...
                    'Color', ...
                    [0.72 0.72 0.72], ...
                    'LineWidth', ...
                    0.45);
            end
            
            meanSpectrum = ...
                mean( ...
                spectra, ...
                1, ...
                'omitnan');
            
            plot( ...
                app.UIAxes2, ...
                wn, ...
                meanSpectrum, ...
                'k', ...
                'LineWidth', ...
                2.0);
            
            hold( ...
                app.UIAxes2, ...
                'off');
            
            app.UIAxes2.Box = ...
                'on';
            
            xlabel( ...
                app.UIAxes2, ...
                'Wavenumber (cm^{-1})');
            
            ylabel( ...
                app.UIAxes2, ...
                'Intensity');
            
            title( ...
                app.UIAxes2, ...
                { ...
                S.Name, ...
                sprintf( ...
                'Pretreated cluster spectra | total pixels=%d | displayed=%d', ...
                numberSpectra, ...
                numel(displayRows))}, ...
                'Interpreter', ...
                'none');
            
        end
        
        
        function tracePNGPath = resolveSampleTracePNG(app, sampleName)
            
            tracePNGPath = '';
            
            readableFolder = ...
                fullfile( ...
                app.SingleExtractReadableRoot, ...
                sampleName);
            
            if ~exist(readableFolder, 'dir')
                return
            end
            
            preferredCandidates = { ...
                fullfile(readableFolder, [sampleName '_trace.png']), ...
                fullfile(readableFolder, 'trace.png'), ...
                fullfile(readableFolder, [sampleName '.png'])};
            
            for candidateNumber = 1:numel(preferredCandidates)
                
                if isfile(preferredCandidates{candidateNumber})
                    
                    tracePNGPath = ...
                        preferredCandidates{candidateNumber};
                    
                    return
                end
            end
            
            pngFiles = ...
                dir( ...
                fullfile( ...
                readableFolder, ...
                '*.png'));
            
            if numel(pngFiles) == 1
                
                tracePNGPath = ...
                    fullfile( ...
                    pngFiles(1).folder, ...
                    pngFiles(1).name);
                
            elseif numel(pngFiles) > 1
                
                traceMatch = ...
                    contains( ...
                    lower(string({pngFiles.name})), ...
                    'trace');
                
                traceFiles = ...
                    pngFiles(traceMatch);
                
                if numel(traceFiles) == 1
                    
                    tracePNGPath = ...
                        fullfile( ...
                        traceFiles(1).folder, ...
                        traceFiles(1).name);
                end
            end
            
        end
        
        
        function [cube, wn] = loadReadableRamanCube(app, matPath) %#ok<INUSD>
            
            cube = [];
            wn = [];
            
            S = ...
                load(matPath);
            
            % =============================================================
            % DIRECT VARIABLES
            % =============================================================
            
            possibleCubeFields = { ...
                'Cube', ...
                'RamanCube', ...
                'cube'};
            
            possibleWNFields = { ...
                'Wavenumber', ...
                'Wavenumbers', ...
                'wn'};
            
            for fieldNumber = 1:numel(possibleCubeFields)
                
                fieldName = ...
                    possibleCubeFields{fieldNumber};
                
                if isfield(S, fieldName) && ...
                        isnumeric(S.(fieldName)) && ...
                        ndims(S.(fieldName)) == 3
                    
                    cube = ...
                        double(S.(fieldName));
                    
                    break
                end
            end
            
            for fieldNumber = 1:numel(possibleWNFields)
                
                fieldName = ...
                    possibleWNFields{fieldNumber};
                
                if isfield(S, fieldName) && ...
                        isnumeric(S.(fieldName))
                    
                    wnCandidate = ...
                        double(S.(fieldName)(:));
                    
                    if isvector(wnCandidate)
                        
                        wn = ...
                            wnCandidate;
                        
                        break
                    end
                end
            end
            
            % =============================================================
            % SEARCH ONE LEVEL INSIDE STRUCTS
            % =============================================================
            
            if isempty(cube) || isempty(wn)
                
                topFields = ...
                    fieldnames(S);
                
                for topNumber = 1:numel(topFields)
                    
                    currentValue = ...
                        S.(topFields{topNumber});
                    
                    if ~isstruct(currentValue)
                        continue
                    end
                    
                    if numel(currentValue) ~= 1
                        continue
                    end
                    
                    if isempty(cube)
                        
                        for fieldNumber = 1:numel(possibleCubeFields)
                            
                            fieldName = ...
                                possibleCubeFields{fieldNumber};
                            
                            if isfield(currentValue, fieldName) && ...
                                    isnumeric(currentValue.(fieldName)) && ...
                                    ndims(currentValue.(fieldName)) == 3
                                
                                cube = ...
                                    double(currentValue.(fieldName));
                                
                                break
                            end
                        end
                    end
                    
                    if isempty(wn)
                        
                        for fieldNumber = 1:numel(possibleWNFields)
                            
                            fieldName = ...
                                possibleWNFields{fieldNumber};
                            
                            if isfield(currentValue, fieldName) && ...
                                    isnumeric(currentValue.(fieldName))
                                
                                wnCandidate = ...
                                    double(currentValue.(fieldName)(:));
                                
                                if isvector(wnCandidate)
                                    
                                    wn = ...
                                        wnCandidate;
                                    
                                    break
                                end
                            end
                        end
                    end
                    
                    if ~isempty(cube) && ...
                            ~isempty(wn)
                        
                        break
                    end
                end
            end
            
            % =============================================================
            % VALIDATION
            % =============================================================
            
            if isempty(cube)
                
                error( ...
                    'single_extract:ReadableCubeMissing', ...
                    sprintf([ ...
                    'Could not find a 3-D Raman cube in:\n\n%s'], ...
                    matPath));
            end
            
            if isempty(wn)
                
                error( ...
                    'single_extract:ReadableWavenumberMissing', ...
                    sprintf([ ...
                    'Could not find Wavenumber in:\n\n%s'], ...
                    matPath));
            end
            
            if numel(wn) ~= size(cube, 3)
                
                error( ...
                    'single_extract:ReadableDimensionMismatch', ...
                    sprintf([ ...
                    'Readable Raman cube and Wavenumber are not aligned.\n\n' ...
                    'Cube spectral channels: %d\n' ...
                    'Wavenumber values: %d\n\n%s'], ...
                    size(cube, 3), ...
                    numel(wn), ...
                    matPath));
            end
            
        end


        function rgbImage = buildClusterRGBImage(app)
            
            [ny, nx] = ...
                size(app.ClusterLabelMap);
            
            rgbImage = ...
                zeros(ny, nx, 3);
            
            for i = 1:numel(app.Clusters)
                
                C = ...
                    app.Clusters(i);
                
                if ~C.Active || ...
                        isempty(C.PixelIdx)
                    
                    continue
                end
                
                pixelIdx = ...
                    C.PixelIdx(:);
                
                clusterColor = ...
                    max(0, min(1, double(C.Color(:).')));
                
                for channel = 1:3
                    
                    colorPlane = ...
                        rgbImage(:, :, channel);
                    
                    colorPlane(pixelIdx) = ...
                        clusterColor(channel);
                    
                    rgbImage(:, :, channel) = ...
                        colorPlane;
                end
            end
            
        end
        
        
        function displayClusterMap(app)
            
            if isempty(app.ClusterLabelMap) || ...
                    isempty(app.PreviewMap)
                
                return
            end
            
            % =============================================================
            % 1. BUILD CLUSTER RGB OVERLAY
            % =============================================================
            
            rgbImage = ...
                app.buildClusterRGBImage();
            
            activeMask = ...
                app.ClusterLabelMap > 0 & ...
                app.ValidMask2D;
            
            % =============================================================
            % 2. DRAW TRACE MAP AS BASE LAYER
            % =============================================================
            
            app.resetImageAxis(app.UIAxes);
            
            baseHandle = ...
                imagesc( ...
                app.UIAxes, ...
                app.PreviewMap);
            
            baseHandle.HitTest = ...
                'off';
            
            axis(app.UIAxes, 'image');
            
            app.UIAxes.YDir = ...
                'normal';
            
            app.UIAxes.Color = ...
                [0 0 0];
            
            % Grayscale is best for displaying colored cluster overlays.
            colormap(app.UIAxes, gray(256));
            
            traceValues = ...
                app.PreviewMap( ...
                app.ValidMask2D & ...
                isfinite(app.PreviewMap));
            
            if ~isempty(traceValues)
                
                traceLow = ...
                    prctile(traceValues, 2);
                
                traceHigh = ...
                    prctile(traceValues, 98);
                
                if isfinite(traceLow) && ...
                        isfinite(traceHigh) && ...
                        traceLow < traceHigh
                    
                    clim( ...
                        app.UIAxes, ...
                        [traceLow traceHigh]);
                end
            end
            
            hold(app.UIAxes, 'on');
            
            % =============================================================
            % 3. ADD TRANSPARENT CLUSTER OVERLAY
            % =============================================================
            
            overlayHandle = ...
                image( ...
                app.UIAxes, ...
                rgbImage);
            
            overlayHandle.AlphaData = ...
                app.ClusterOverlayAlpha .* ...
                double(activeMask);
            
            overlayHandle.AlphaDataMapping = ...
                'none';
            
            overlayHandle.HitTest = ...
                'off';
            
            % =============================================================
            % 4. DRAW BOUNDARIES OF ALL ACTIVE CLUSTERS
            % =============================================================
            
            activeIDs = ...
                unique(app.ClusterLabelMap(activeMask));
            
            activeIDs = ...
                activeIDs(activeIDs > 0);
            
            for clusterID = activeIDs(:)'
                
                clusterMask = ...
                    app.ClusterLabelMap == clusterID;
                
                clusterIndex = ...
                    app.findClusterIndexByID(clusterID);
                
                if isempty(clusterIndex)
                    continue
                end
                
                boundaryColor = ...
                    app.Clusters(clusterIndex).Color;
                
                contour( ...
                    app.UIAxes, ...
                    clusterMask, ...
                    [0.5 0.5], ...
                    'Color', ...
                    boundaryColor, ...
                    'LineWidth', ...
                    0.65, ...
                    'HitTest', ...
                    'off');
            end
            
            % =============================================================
            % 5. HIGHLIGHT SELECTED CLUSTERS
            % =============================================================
            
            selectedMask = ...
                false(size(app.ClusterLabelMap));
            
            for id = app.SelectedClusterIDs(:)'
                
                clusterIndex = ...
                    app.findClusterIndexByID(id);
                
                if isempty(clusterIndex)
                    continue
                end
                
                if app.Clusters(clusterIndex).Active
                    
                    selectedMask( ...
                        app.Clusters(clusterIndex).PixelIdx) = ...
                        true;
                end
            end
            
            if any(selectedMask(:))
                
                contour( ...
                    app.UIAxes, ...
                    selectedMask, ...
                    [0.5 0.5], ...
                    'w', ...
                    'LineWidth', ...
                    2.2, ...
                    'HitTest', ...
                    'off');
                
                contour( ...
                    app.UIAxes, ...
                    selectedMask, ...
                    [0.5 0.5], ...
                    'k', ...
                    'LineWidth', ...
                    0.9, ...
                    'HitTest', ...
                    'off');
            end
            
            hold(app.UIAxes, 'off');
            
            % =============================================================
            % 6. TITLE
            % =============================================================
            
            title( ...
                app.UIAxes, ...
                { ...
                app.CurrentScanName, ...
                sprintf( ...
                'Trace + transparent cluster overlay | active clusters: %d', ...
                nnz([app.Clusters.Active])), ...
                sprintf( ...
                'Overlay opacity: %.0f%% | white/black = selected', ...
                100 * app.ClusterOverlayAlpha)}, ...
                'Interpreter', ...
                'none');
            
        end
        
        
        % function populateClusterTree(app)
        %
        %     app.IsUpdatingTree = ...
        %         true;
        %
        %     try
        %         delete(app.Tree.Children);
        %     catch
        %     end
        %
        %     % =============================================================
        %     % ROOT
        %     % =============================================================
        %
        %     rootText = ...
        %         app.CurrentScanName;
        %
        %     if isempty(rootText)
        %         rootText = 'Current scan';
        %     end
        %
        %     root = ...
        %         uitreenode( ...
        %         app.Tree, ...
        %         'Text', ...
        %         rootText, ...
        %         'NodeData', ...
        %         struct('Type', 'root'));
        %
        %     % =============================================================
        %     % ACTIVE CLUSTERS
        %     % =============================================================
        %
        %     activeIndices = ...
        %         find([app.Clusters.Active]);
        %
        %     activeRoot = ...
        %         uitreenode( ...
        %         root, ...
        %         'Text', ...
        %         sprintf( ...
        %         'Active clusters (%d)', ...
        %         numel(activeIndices)), ...
        %         'NodeData', ...
        %         struct('Type', 'active_root'));
        %
        %     % Core layers first.
        %     coreLayers = { ...
        %         'Unassigned', ...
        %         'Cell candidate', ...
        %         'Background candidate', ...
        %         'Mixed / uncertain'};
        %
        %     activeClasses = ...
        %         unique( ...
        %         {app.Clusters(activeIndices).Class}, ...
        %         'stable');
        %
        %     customLayers = ...
        %         setdiff( ...
        %         activeClasses, ...
        %         coreLayers, ...
        %         'stable');
        %
        %     classOrder = ...
        %         [coreLayers customLayers];
        %
        %     for classNumber = 1:numel(classOrder)
        %
        %         className = ...
        %             classOrder{classNumber};
        %
        %         clusterIndices = ...
        %             find( ...
        %             [app.Clusters.Active] & ...
        %             strcmp( ...
        %             {app.Clusters.Class}, ...
        %             className));
        %
        %         if isempty(clusterIndices) && ...
        %                 ~ismember(className, coreLayers)
        %
        %             continue
        %         end
        %
        %         classRoot = ...
        %             uitreenode( ...
        %             activeRoot, ...
        %             'Text', ...
        %             sprintf( ...
        %             '%s (%d)', ...
        %             className, ...
        %             numel(clusterIndices)), ...
        %             'NodeData', ...
        %             struct( ...
        %             'Type', ...
        %             'class_root', ...
        %             'Class', ...
        %             className));
        %
        %         for clusterIndex = clusterIndices
        %
        %             C = ...
        %                 app.Clusters(clusterIndex);
        %
        %             if app.PixelAssessmentReady && ...
        %                     isfield( ...
        %                     C.Metrics, ...
        %                     'EstimatedBackgroundPurity')
        %
        %                 nodeText = ...
        %                     sprintf( ...
        %                     ['%s | ID=%d | n=%d | ' ...
        %                     'BG=%.1f%% | Cell=%.1f%% | U=%.1f%%'], ...
        %                     C.Name, ...
        %                     C.ID, ...
        %                     numel(C.PixelIdx), ...
        %                     100 * C.Metrics.EstimatedBackgroundPurity, ...
        %                     100 * C.Metrics.EstimatedCellPurity, ...
        %                     100 * C.Metrics.UncertainPixelFraction);
        %
        %             else
        %
        %                 nodeText = ...
        %                     sprintf( ...
        %                     '%s | ID=%d | pixels=%d', ...
        %                     C.Name, ...
        %                     C.ID, ...
        %                     numel(C.PixelIdx));
        %             end
        %
        %             clusterNode = ...
        %                 uitreenode( ...
        %                 classRoot, ...
        %                 'Text', ...
        %                 nodeText, ...
        %                 'NodeData', ...
        %                 struct( ...
        %                 'Type', ...
        %                 'cluster', ...
        %                 'ID', ...
        %                 C.ID));
        %
        %             try
        %
        %                 nodeStyle = ...
        %                     uistyle( ...
        %                     'FontColor', ...
        %                     C.Color, ...
        %                     'FontWeight', ...
        %                     'bold');
        %
        %                 addStyle( ...
        %                     app.Tree, ...
        %                     nodeStyle, ...
        %                     'node', ...
        %                     clusterNode);
        %
        %             catch
        %             end
        %         end
        %
        %         if ~isempty(clusterIndices)
        %             expand(classRoot);
        %         end
        %     end
        %
        %     % =============================================================
        %     % SUMMARY BEFORE HISTORY
        %     % =============================================================
        %
        %     uitreenode( ...
        %         root, ...
        %         'Text', ...
        %         sprintf( ...
        %         'Summary | active=%d | valid pixels=%d', ...
        %         numel(activeIndices), ...
        %         nnz(app.ValidMask2D)), ...
        %         'NodeData', ...
        %         struct('Type', 'summary'));
        %
        %     % =============================================================
        %     % VISUAL SEPARATOR
        %     % =============================================================
        %
        %     separatorNode = ...
        %         uitreenode( ...
        %         root, ...
        %         'Text', ...
        %         '──────── previous cluster states ────────', ...
        %         'NodeData', ...
        %         struct('Type', 'separator'));
        %
        %     try
        %
        %         separatorStyle = ...
        %             uistyle( ...
        %             'FontColor', ...
        %             [0.55 0.55 0.55], ...
        %             'FontAngle', ...
        %             'italic', ...
        %             'FontSize', ...
        %             9);
        %
        %         addStyle( ...
        %             app.Tree, ...
        %             separatorStyle, ...
        %             'node', ...
        %             separatorNode);
        %
        %     catch
        %     end
        %
        %     % =============================================================
        %     % INACTIVE / HISTORY
        %     % =============================================================
        %
        %     inactiveIndices = ...
        %         find(~[app.Clusters.Active]);
        %
        %     inactiveRoot = ...
        %         uitreenode( ...
        %         root, ...
        %         'Text', ...
        %         sprintf( ...
        %         'Inactive / previous states (%d)', ...
        %         numel(inactiveIndices)), ...
        %         'NodeData', ...
        %         struct('Type', 'inactive_root'));
        %
        %     try
        %
        %         inactiveRootStyle = ...
        %             uistyle( ...
        %             'FontColor', ...
        %             [0.50 0.50 0.50], ...
        %             'FontAngle', ...
        %             'italic', ...
        %             'FontSize', ...
        %             9);
        %
        %         addStyle( ...
        %             app.Tree, ...
        %             inactiveRootStyle, ...
        %             'node', ...
        %             inactiveRoot);
        %
        %     catch
        %     end
        %
        %     for clusterIndex = inactiveIndices
        %
        %         C = ...
        %             app.Clusters(clusterIndex);
        %
        %         inactiveNode = ...
        %             uitreenode( ...
        %             inactiveRoot, ...
        %             'Text', ...
        %             sprintf( ...
        %             '%s | ID=%d | %s | n=%d', ...
        %             C.Name, ...
        %             C.ID, ...
        %             C.Class, ...
        %             numel(C.PixelIdx)), ...
        %             'NodeData', ...
        %             struct( ...
        %             'Type', ...
        %             'inactive_cluster', ...
        %             'ID', ...
        %             C.ID));
        %
        %         try
        %
        %             inactiveNodeStyle = ...
        %                 uistyle( ...
        %                 'FontColor', ...
        %                 [0.58 0.58 0.58], ...
        %                 'FontSize', ...
        %                 8);
        %
        %             addStyle( ...
        %                 app.Tree, ...
        %                 inactiveNodeStyle, ...
        %                 'node', ...
        %                 inactiveNode);
        %
        %         catch
        %         end
        %     end
        %
        %     % =============================================================
        %     % EXPANSION STATE
        %     %
        %     % Active information open.
        %     % Inactive history remains collapsed.
        %     % =============================================================
        %
        %     expand(root);
        %     expand(activeRoot);
        %
        %     try
        %         collapse(inactiveRoot);
        %     catch
        %     end
        %
        %     app.IsUpdatingTree = ...
        %         false;
        %
        % end


        function populateClusterTree(app)
            
            app.IsUpdatingTree = ...
                true;
            
            try
                delete(app.Tree.Children);
            catch
            end
            
            % =============================================================
            % ROOT
            % =============================================================
            
            rootText = ...
                app.CurrentScanName;
            
            if isempty(rootText)
                rootText = 'Current scan';
            end
            
            root = ...
                uitreenode( ...
                app.Tree, ...
                'Text', ...
                rootText, ...
                'NodeData', ...
                struct('Type', 'root'));
            
            % =============================================================
            % CLUSTERING STABILITY SUMMARY
            % =============================================================
            
            if app.ClusterReady && ...
                    isfinite(app.ClusterOverallMedianARI)
                
                stabilityNode = ...
                    uitreenode( ...
                    root, ...
                    'Text', ...
                    sprintf( ...
                    ['CLUSTERING | PCs=%d | variance=%.1f%% | ' ...
                    'runs=%d | ARI=%.3f | medoid=%d | %s'], ...
                    app.NumPCUsed, ...
                    sum(app.PCAExplained(1:app.NumPCUsed)), ...
                    app.ClusterStabilityRuns, ...
                    app.ClusterOverallMedianARI, ...
                    app.ClusterSelectedRun, ...
                    app.ClusterStabilityLevel), ...
                    'NodeData', ...
                    struct( ...
                    'Type', ...
                    'clustering_stability'));
                try
                    
                    stabilityStyle = ...
                        uistyle( ...
                        'FontWeight', ...
                        'bold', ...
                        'FontColor', ...
                        [0.10 0.38 0.32]);
                    
                    addStyle( ...
                        app.Tree, ...
                        stabilityStyle, ...
                        'node', ...
                        stabilityNode);
                    
                catch
                end
            end
            
            % =============================================================
            % ACTIVE CLUSTERS
            % =============================================================
            
            activeIndices = ...
                find([app.Clusters.Active]);
            
            activeRoot = ...
                uitreenode( ...
                root, ...
                'Text', ...
                sprintf( ...
                'Active clusters (%d)', ...
                numel(activeIndices)), ...
                'NodeData', ...
                struct('Type', 'active_root'));
            
            coreLayers = { ...
                'Unassigned', ...
                'Cell candidate', ...
                'Background candidate', ...
                'Mixed / uncertain', ...
                'Artifact candidate'};
            
            if isempty(activeIndices)
                
                activeClasses = {};
                
            else
                
                activeClasses = ...
                    unique( ...
                    {app.Clusters(activeIndices).Class}, ...
                    'stable');
            end
            
            customLayers = ...
                setdiff( ...
                activeClasses, ...
                coreLayers, ...
                'stable');
            
            classOrder = ...
                [coreLayers customLayers];
            
            for classNumber = 1:numel(classOrder)
                
                className = ...
                    classOrder{classNumber};
                
                clusterIndices = ...
                    find( ...
                    [app.Clusters.Active] & ...
                    strcmp( ...
                    {app.Clusters.Class}, ...
                    className));
                
                if isempty(clusterIndices) && ...
                        ~ismember(className, coreLayers)
                    
                    continue
                end
                
                classRoot = ...
                    uitreenode( ...
                    activeRoot, ...
                    'Text', ...
                    sprintf( ...
                    '%s (%d)', ...
                    className, ...
                    numel(clusterIndices)), ...
                    'NodeData', ...
                    struct( ...
                    'Type', ...
                    'class_root', ...
                    'Class', ...
                    className));
                % =============================================================
                % DERIVED STRONG / MEDIUM COMPONENT SUPPORT FOR THIS LAYER
                % =============================================================
                
                app.addComponentAggregateNodesToClassRoot( ...
                    classRoot, ...
                    className);
                
                for clusterIndex = clusterIndices
                    
                    C = ...
                        app.Clusters(clusterIndex);
                    
                    % =====================================================
                    % SHORT EVIDENCE-BASED NODE TEXT
                    % =====================================================
                    
                    if app.PixelAssessmentReady && ...
                            isfield(C.Metrics, 'EvidenceStatus')
                        
                        nodeText = ...
                            sprintf( ...
                            '%s | n=%d | %s', ...
                            C.Name, ...
                            numel(C.PixelIdx), ...
                            C.Metrics.EvidenceStatus);
                        
                    else
                        
                        nodeText = ...
                            sprintf( ...
                            '%s | ID=%d | n=%d', ...
                            C.Name, ...
                            C.ID, ...
                            numel(C.PixelIdx));
                    end
                    
                    clusterNode = ...
                        uitreenode( ...
                        classRoot, ...
                        'Text', ...
                        nodeText, ...
                        'NodeData', ...
                        struct( ...
                        'Type', ...
                        'cluster', ...
                        'ID', ...
                        C.ID, ...
                        'ClusterID', ...
                        C.ID, ...
                        'Name', ...
                        C.Name, ...
                        'PixelIdx', ...
                        double(C.PixelIdx(:)), ...
                        'Color', ...
                        double(C.Color(:).'), ...
                        'PurityClass', ...
                        'Segmentation cluster'));
                    % =====================================================
                    % COMPONENT ASSESSMENT CHILD NODES
                    % =====================================================
                    
                    componentIndices = ...
                        find( ...
                        [app.ComponentAssessments.ClusterID] == ...
                        C.ID);
                    
                    if ~isempty(componentIndices)
                        
                        assessmentRoot = ...
                            uitreenode( ...
                            clusterNode, ...
                            'Text', ...
                            sprintf( ...
                            'Reference components (%d)', ...
                            numel(componentIndices)), ...
                            'NodeData', ...
                            struct( ...
                            'Type', ...
                            'component_root', ...
                            'ClusterID', ...
                            C.ID));
                        
                        for assessmentNumber = componentIndices(:)'
                            
                            A = ...
                                app.ComponentAssessments(assessmentNumber);
                            
                            A = ...
                                app.updateComponentAssessmentStaleState(A);
                            
                            app.ComponentAssessments(assessmentNumber) = ...
                                A;
                            
                            if A.IsStale
                                
                                statusSuffix = ...
                                    ' | STALE';
                                
                            else
                                
                                statusSuffix = ...
                                    '';
                            end
                            
                            % =============================================================
                            % BUILD COMPONENT-ASSESSMENT NODE TEXT
                            %
                            % Prefer the 750/785 ratio Strong fraction because this is the
                            % quantity used by the current three-level component map.
                            % =============================================================
                            
                            if isfield(A.Metrics, 'RatioStrongFraction') && ...
                                    isfinite(A.Metrics.RatioStrongFraction)
                                
                                assessmentNodeText = ...
                                    sprintf( ...
                                    '%s | ratio strong=%.1f%%%s', ...
                                    A.ComponentName, ...
                                    100 * A.Metrics.RatioStrongFraction, ...
                                    statusSuffix);
                                
                            elseif isfield(A.Metrics, 'RatioStrongCount') && ...
                                    isfield(A.Metrics, 'RatioValidPixelCount') && ...
                                    isfinite(A.Metrics.RatioValidPixelCount) && ...
                                    A.Metrics.RatioValidPixelCount > 0
                                
                                ratioStrongFraction = ...
                                    A.Metrics.RatioStrongCount / ...
                                    A.Metrics.RatioValidPixelCount;
                                
                                assessmentNodeText = ...
                                    sprintf( ...
                                    '%s | ratio strong=%.1f%%%s', ...
                                    A.ComponentName, ...
                                    100 * ratioStrongFraction, ...
                                    statusSuffix);
                                
                            elseif isfield(A.Metrics, 'HighSupportFraction') && ...
                                    isfinite(A.Metrics.HighSupportFraction)
                                
                                % Fallback for older assessment records that do not yet contain
                                % ratio metrics.
                                assessmentNodeText = ...
                                    sprintf( ...
                                    '%s | spectral high=%.1f%%%s', ...
                                    A.ComponentName, ...
                                    100 * A.Metrics.HighSupportFraction, ...
                                    statusSuffix);
                                
                            else
                                
                                assessmentNodeText = ...
                                    sprintf( ...
                                    '%s | assessment available%s', ...
                                    A.ComponentName, ...
                                    statusSuffix);
                            end
                            
                            assessmentNode = ...
                                uitreenode( ...
                                assessmentRoot, ...
                                'Text', ...
                                assessmentNodeText, ...
                                'NodeData', ...
                                struct( ...
                                'Type', ...
                                'component_assessment', ...
                                'AssessmentIndex', ...
                                assessmentNumber, ...
                                'ClusterID', ...
                                C.ID));
                            
                            try
                                
                                if A.IsStale
                                    
                                    assessmentStyle = ...
                                        uistyle( ...
                                        'FontColor', ...
                                        [0.65 0.25 0.10], ...
                                        'FontAngle', ...
                                        'italic');
                                    
                                else
                                    
                                    assessmentStyle = ...
                                        uistyle( ...
                                        'FontColor', ...
                                        [0.20 0.55 0.85]);
                                end
                                
                                addStyle( ...
                                    app.Tree, ...
                                    assessmentStyle, ...
                                    'node', ...
                                    assessmentNode);
                                
                            catch
                            end
                        end
                        
                        try
                            expand(assessmentRoot);
                        catch
                        end
                    end
                    
                    try
                        
                        nodeStyle = ...
                            uistyle( ...
                            'FontColor', ...
                            C.Color, ...
                            'FontWeight', ...
                            'bold');
                        
                        addStyle( ...
                            app.Tree, ...
                            nodeStyle, ...
                            'node', ...
                            clusterNode);
                        
                    catch
                    end
                end
                
                if ~isempty(clusterIndices)
                    expand(classRoot);
                end
            end
            
            
            % =============================================================
            % PURITY RESULTS
            %
            % Displayed below the active segmentation clusters.
            % These nodes are selectable spectral groups and do not modify
            % the segmentation cluster objects or ClusterLabelMap.
            % =============================================================
            
            purityRoot = ...
                app.addPurityResultsToTree( ...
                root, ...
                activeIndices);
            
            % =============================================================
            % SUMMARY
            % =============================================================
            
            if app.PixelAssessmentReady
                
                sufficientGTCount = 0;
                intermediateCount = 0;
                
                for clusterIndex = activeIndices
                    
                    M = ...
                        app.Clusters(clusterIndex).Metrics;
                    
                    if isfield(M, 'HasSufficientGroundTruth') && ...
                            M.HasSufficientGroundTruth
                        
                        sufficientGTCount = ...
                            sufficientGTCount + 1;
                    end
                    
                    if isfield(M, 'EvidenceStatus') && ...
                            contains( ...
                            lower(M.EvidenceStatus), ...
                            'intermediate')
                        
                        intermediateCount = ...
                            intermediateCount + 1;
                    end
                end
                
                summaryText = ...
                    sprintf( ...
                    ['Summary | active=%d | GT-supported=%d | ' ...
                    'intermediate=%d'], ...
                    numel(activeIndices), ...
                    sufficientGTCount, ...
                    intermediateCount);
                
            else
                
                summaryText = ...
                    sprintf( ...
                    'Summary | active=%d | valid pixels=%d', ...
                    numel(activeIndices), ...
                    nnz(app.ValidMask2D));
            end
            
            uitreenode( ...
                root, ...
                'Text', ...
                summaryText, ...
                'NodeData', ...
                struct('Type', 'summary'));
            
            % =============================================================
            % INACTIVE HISTORY
            % =============================================================
            
            inactiveIndices = ...
                find(~[app.Clusters.Active]);
            
            inactiveRoot = ...
                uitreenode( ...
                root, ...
                'Text', ...
                sprintf( ...
                'Inactive / previous states (%d)', ...
                numel(inactiveIndices)), ...
                'NodeData', ...
                struct('Type', 'inactive_root'));
            
            for clusterIndex = inactiveIndices
                
                C = ...
                    app.Clusters(clusterIndex);
                
                inactiveNode = ...
                    uitreenode( ...
                    inactiveRoot, ...
                    'Text', ...
                    sprintf( ...
                    '%s | ID=%d | %s | n=%d', ...
                    C.Name, ...
                    C.ID, ...
                    C.Class, ...
                    numel(C.PixelIdx)), ...
                    'NodeData', ...
                    struct( ...
                    'Type', ...
                    'inactive_cluster', ...
                    'ID', ...
                    C.ID));
                
                try
                    
                    inactiveStyle = ...
                        uistyle( ...
                        'FontColor', ...
                        [0.58 0.58 0.58], ...
                        'FontAngle', ...
                        'italic', ...
                        'FontSize', ...
                        8);
                    
                    addStyle( ...
                        app.Tree, ...
                        inactiveStyle, ...
                        'node', ...
                        inactiveNode);
                    
                catch
                end
            end
            
            expand(root);
            expand(activeRoot);
            
            if ~isempty(purityRoot)
                
                try
                    expand(purityRoot);
                catch
                end
            end
            
            try
                collapse(inactiveRoot);
            catch
            end
            
            app.IsUpdatingTree = ...
                false;
            
        end


        function assessmentIndex = ...
                getSelectedComponentAssessmentIndexFromTree(app)
            
            assessmentIndex = [];
            
            nodes = ...
                app.Tree.SelectedNodes;
            
            for nodeNumber = 1:numel(nodes)
                
                data = ...
                    nodes(nodeNumber).NodeData;
                
                if isempty(data) || ...
                        ~isstruct(data) || ...
                        ~isfield(data, 'Type')
                    
                    continue
                end
                
                if strcmp( ...
                        data.Type, ...
                        'component_assessment') && ...
                        isfield( ...
                        data, ...
                        'AssessmentIndex')
                    
                    assessmentIndex = ...
                        double(data.AssessmentIndex);
                    
                    return
                end
            end
            
        end
        
        function ids = getSelectedClusterIDsFromTree(app)
            
            ids = [];
            
            nodes = ...
                app.Tree.SelectedNodes;
            
            for nodeNumber = 1:numel(nodes)
                
                data = ...
                    nodes(nodeNumber).NodeData;
                
                if isempty(data) || ...
                        ~isstruct(data) || ...
                        ~isfield(data, 'Type')
                    
                    continue
                end
                
                if strcmp(data.Type, 'cluster') && ...
                        isfield(data, 'ID')
                    
                    ids(end + 1, 1) = ...
                        double(data.ID); %#ok<AGROW>
                    
                elseif strcmp(data.Type, 'component_assessment') && ...
                        isfield(data, 'ClusterID')
                    
                    ids(end + 1, 1) = ...
                        double(data.ClusterID); %#ok<AGROW>
                end
            end
            
            ids = ...
                unique(ids, 'stable');
            
        end
        
        
        function idx = findClusterIndexByID(app, clusterID)
            
            idx = [];
            
            if isempty(app.Clusters)
                return
            end
            
            allIDs = ...
                [app.Clusters.ID];
            
            idx = ...
                find(allIDs == clusterID, 1, 'first');
            
        end

        function displaySelectedClusterSpectra(app)
            
            ids = ...
                app.SelectedClusterIDs;
            
            if isempty(ids)
                
                app.displayAllClusterMeans();
                return
            end
            
            app.resetSpectrumAxis(app.UIAxes_3);
            
            hold(app.UIAxes_3, 'on');
            
            yAll = [];
            legendText = {};
            
            for id = ids(:)'
                
                idx = ...
                    app.findClusterIndexByID(id);
                
                if isempty(idx)
                    continue
                end
                
                C = ...
                    app.Clusters(idx);
                
                if ~C.Active
                    continue
                end
                
                meanSpec = ...
                    double(C.MeanSpectrum(:));
                
                stdSpec = ...
                    double(C.StdSpectrum(:));
                
                wn = ...
                    app.Wavenumber(:);
                
                upper = ...
                    meanSpec + stdSpec;
                
                lower = ...
                    meanSpec - stdSpec;
                
                if numel(ids) == 1
                    
                    fill( ...
                        app.UIAxes_3, ...
                        [wn; flipud(wn)], ...
                        [upper; flipud(lower)], ...
                        C.Color, ...
                        'FaceAlpha', ...
                        0.18, ...
                        'EdgeColor', ...
                        'none', ...
                        'HitTest', ...
                        'off');
                    
                end
                
                plot( ...
                    app.UIAxes_3, ...
                    wn, ...
                    meanSpec, ...
                    'Color', ...
                    C.Color, ...
                    'LineWidth', ...
                    1.6);
                
                yAll = ...
                    [yAll; upper; lower]; %#ok<AGROW>
                
                legendText{end + 1} = ...
                    sprintf( ...
                    '%s | %s | n=%d', ...
                    C.Name, ...
                    C.Class, ...
                    numel(C.PixelIdx)); %#ok<AGROW>
            end
            
            hold(app.UIAxes_3, 'off');
            
            title( ...
                app.UIAxes_3, ...
                sprintf( ...
                'Selected cluster spectra | clusters=%d', ...
                numel(ids)), ...
                'Interpreter', ...
                'none');
            
            if ~isempty(legendText)
                
                legend( ...
                    app.UIAxes_3, ...
                    legendText, ...
                    'Location', ...
                    'best', ...
                    'Interpreter', ...
                    'none');
            end
            
            app.setNiceSpectralLimits( ...
                app.UIAxes_3, ...
                app.Wavenumber, ...
                yAll);
            
        end
        
        
        function displayAllClusterMeans(app)
            
            if isempty(app.Clusters)
                return
            end
            
            app.resetSpectrumAxis(app.UIAxes_3);
            
            hold(app.UIAxes_3, 'on');
            
            legendText = {};
            yAll = [];
            
            for i = 1:numel(app.Clusters)
                
                C = ...
                    app.Clusters(i);
                
                if ~C.Active
                    continue
                end
                
                plot( ...
                    app.UIAxes_3, ...
                    app.Wavenumber, ...
                    C.MeanSpectrum, ...
                    'Color', ...
                    C.Color, ...
                    'LineWidth', ...
                    1.4);
                
                yAll = ...
                    [yAll; C.MeanSpectrum(:)]; %#ok<AGROW>
                
                legendText{end + 1} = ...
                    sprintf( ...
                    '%s | %s | n=%d', ...
                    C.Name, ...
                    C.Class, ...
                    numel(C.PixelIdx)); %#ok<AGROW>
            end
            
            hold(app.UIAxes_3, 'off');
            
            title( ...
                app.UIAxes_3, ...
                'Mean spectra of active clusters', ...
                'Interpreter', ...
                'none');
            
            if ~isempty(legendText)
                
                legend( ...
                    app.UIAxes_3, ...
                    legendText, ...
                    'Location', ...
                    'best', ...
                    'Interpreter', ...
                    'none');
            end
            
            app.setNiceSpectralLimits( ...
                app.UIAxes_3, ...
                app.Wavenumber, ...
                yAll);
            
        end
        
        
        function computeAllClusterMetrics(app, nearestDistance)
            
            if nargin < 2
                nearestDistance = [];
            end
            
            totalValid = ...
                max(1, nnz(app.ValidMask2D));
            
            labelRows = ...
                app.getMetricLabelsFromCurrentClusters();
            
            app.MetricLabels = ...
                labelRows;
            
            for i = 1:numel(app.Clusters)
                
                C = ...
                    app.Clusters(i);
                
                if isempty(C.PixelIdx)
                    
                    app.Clusters(i).Metrics = ...
                        struct();
                    
                    continue
                end
                
                rowMask = ...
                    ismember( ...
                    app.MetricPixelIdx, ...
                    C.PixelIdx);
                
                clusterScores = ...
                    app.AllPCAScores(rowMask, :);
                
                clusterConfidence = ...
                    app.PixelConfidence(rowMask);
                
                if isempty(clusterScores)
                    
                    dispersion = NaN;
                    
                else
                    
                    scoreCenter = ...
                        mean(clusterScores, 1, 'omitnan');
                    
                    d = ...
                        sqrt( ...
                        sum( ...
                        (clusterScores - scoreCenter) .^ 2, ...
                        2));
                    
                    dispersion = ...
                        median(d, 'omitnan');
                end
                
                clusterMask = ...
                    false(size(app.ValidMask2D));
                
                clusterMask(C.PixelIdx) = ...
                    true;
                
                cc = ...
                    bwconncomp(clusterMask, 8);
                
                componentCount = ...
                    cc.NumObjects;
                
                if componentCount > 0
                    
                    componentSizes = ...
                        cellfun(@numel, cc.PixelIdxList);
                    
                    largestFraction = ...
                        max(componentSizes) / ...
                        max(1, numel(C.PixelIdx));
                    
                    isolatedFraction = ...
                        nnz(componentSizes <= 2) / ...
                        max(1, componentCount);
                    
                else
                    
                    largestFraction = 0;
                    isolatedFraction = 0;
                end
                
                M = struct();
                
                M.PixelCount = ...
                    numel(C.PixelIdx);
                
                M.PixelFraction = ...
                    numel(C.PixelIdx) / totalValid;
                
                M.MedianConfidence = ...
                    median(clusterConfidence, 'omitnan');
                
                M.LowConfidenceFraction = ...
                    mean(clusterConfidence < 0.20, 'omitnan');
                
                M.MedianPCADistance = ...
                    dispersion;
                
                M.ConnectedComponents = ...
                    componentCount;
                
                M.LargestComponentFraction = ...
                    largestFraction;
                
                M.IsolatedComponentFraction = ...
                    isolatedFraction;
                
                if M.MedianConfidence < 0.15
                    
                    M.Recommendation = ...
                        'Weak centroid separation; review or split.';
                    
                elseif M.LargestComponentFraction < 0.40
                    
                    M.Recommendation = ...
                        'Spatially fragmented cluster; review morphology.';
                    
                elseif M.LowConfidenceFraction > 0.30
                    
                    M.Recommendation = ...
                        'Many borderline pixels; inspect before assigning.';
                    
                else
                    
                    M.Recommendation = ...
                        'Cluster is suitable for manual interpretation.';
                end
                
                if ~isempty(nearestDistance)
                    
                    if numel(nearestDistance) == numel(app.MetricPixelIdx)
                        
                        M.MedianNearestDistance = ...
                            median( ...
                            nearestDistance(rowMask), ...
                            'omitnan');
                        
                    else
                        
                        M.MedianNearestDistance = ...
                            NaN;
                    end
                    
                else
                    
                    M.MedianNearestDistance = ...
                        NaN;
                end
                
                app.Clusters(i).Metrics = ...
                    M;
            end
            
            app.GlobalClusterMetrics = struct();
            
            app.GlobalClusterMetrics.ActiveClusters = ...
                nnz([app.Clusters.Active]);
            
            app.GlobalClusterMetrics.ValidPixels = ...
                totalValid;
            
            app.GlobalClusterMetrics.MedianConfidence = ...
                median(app.PixelConfidence, 'omitnan');
            
        end
        
        
        function labels = getMetricLabelsFromCurrentClusters(app)
            
            labels = ...
                zeros(numel(app.MetricPixelIdx), 1);
            
            for i = 1:numel(app.Clusters)
                
                C = app.Clusters(i);
                
                if ~C.Active
                    continue
                end
                
                rowMask = ...
                    ismember( ...
                    app.MetricPixelIdx, ...
                    C.PixelIdx);
                
                labels(rowMask) = ...
                    C.ID;
            end
            
        end
        
        
        % function updateClusterInformationText(app)
        %
        %     ids = ...
        %         app.SelectedClusterIDs;
        %
        %     if isempty(ids)
        %
        %         if app.PixelAssessmentReady
        %
        %             app.updatePixelAssessmentSummary();
        %
        %         else
        %
        %             app.TextArea.Value = { ...
        %                 'CLUSTER OVERVIEW'; ...
        %                 '================================'; ...
        %                 ''; ...
        %                 ['Scan: ' app.CurrentScanName]; ...
        %                 sprintf('Active clusters: %d', ...
        %                 nnz([app.Clusters.Active])); ...
        %                 sprintf('Valid pixels: %d', ...
        %                 nnz(app.ValidMask2D)); ...
        %                 sprintf('PCA components used: %d', ...
        %                 app.NumPCUsed); ...
        %                 sprintf('Median pixel confidence: %.3f', ...
        %                 median(app.PixelConfidence, 'omitnan')); ...
        %                 ''; ...
        %                 'Select one or more cluster nodes for details.'};
        %         end
        %
        %         return
        %     end
        %
        %     if numel(ids) > 1
        %
        %         totalPixels = 0;
        %
        %         for id = ids(:)'
        %
        %             clusterIndex = ...
        %                 app.findClusterIndexByID(id);
        %
        %             if ~isempty(clusterIndex)
        %
        %                 totalPixels = ...
        %                     totalPixels + ...
        %                     numel( ...
        %                     app.Clusters(clusterIndex).PixelIdx);
        %             end
        %         end
        %
        %         app.TextArea.Value = { ...
        %             'MULTIPLE CLUSTERS SELECTED'; ...
        %             '================================'; ...
        %             ''; ...
        %             sprintf('Selected clusters: %d', ...
        %             numel(ids)); ...
        %             sprintf('Combined pixels: %d', ...
        %             totalPixels); ...
        %             ''; ...
        %             'Select one cluster to inspect purity details.'};
        %
        %         return
        %     end
        %
        %     clusterIndex = ...
        %         app.findClusterIndexByID(ids(1));
        %
        %     if isempty(clusterIndex)
        %         return
        %     end
        %
        %     C = ...
        %         app.Clusters(clusterIndex);
        %
        %     M = ...
        %         C.Metrics;
        %
        %     textLines = { ...
        %         'CLUSTER INFORMATION'; ...
        %         '================================'; ...
        %         ''; ...
        %         ['Name: ' C.Name]; ...
        %         sprintf('ID: %d', C.ID); ...
        %         sprintf('Parent ID: %d', C.ParentID); ...
        %         ['Assigned layer: ' C.Class]; ...
        %         sprintf('Active: %d', C.Active); ...
        %         ''; ...
        %         'SIZE'; ...
        %         sprintf('Pixels: %d', M.PixelCount); ...
        %         sprintf('Pixel fraction: %.2f%%', ...
        %         100 * M.PixelFraction); ...
        %         ''; ...
        %         'CLUSTER-LEVEL QC'; ...
        %         sprintf('Median assignment confidence: %.3f', ...
        %         M.MedianConfidence); ...
        %         sprintf('Low-confidence pixels: %.2f%%', ...
        %         100 * M.LowConfidenceFraction); ...
        %         sprintf('Median PCA dispersion: %.4g', ...
        %         M.MedianPCADistance); ...
        %         sprintf('Connected components: %d', ...
        %         M.ConnectedComponents); ...
        %         sprintf('Largest component fraction: %.2f%%', ...
        %         100 * M.LargestComponentFraction)};
        %
        %     if app.PixelAssessmentReady && ...
        %             isfield(M, 'EstimatedBackgroundPurity')
        %
        %         textLines = ...
        %             [ ...
        %             textLines; ...
        %             { ...
        %             ''; ...
        %             'PIXEL-LEVEL PURITY'; ...
        %             sprintf( ...
        %             'Estimated background purity: %.2f%%', ...
        %             100 * M.EstimatedBackgroundPurity); ...
        %             sprintf( ...
        %             'Estimated cell purity: %.2f%%', ...
        %             100 * M.EstimatedCellPurity); ...
        %             sprintf( ...
        %             'Estimated cell contamination: %.2f%%', ...
        %             100 * M.EstimatedCellContamination); ...
        %             sprintf( ...
        %             'Uncertain pixel fraction: %.2f%%', ...
        %             100 * M.UncertainPixelFraction); ...
        %             sprintf( ...
        %             'Mean cell membership: %.3f', ...
        %             M.MeanCellMembership); ...
        %             sprintf( ...
        %             'Median cell membership: %.3f', ...
        %             M.MedianCellMembership); ...
        %             sprintf( ...
        %             'P90 cell membership: %.3f', ...
        %             M.P90CellMembership); ...
        %             sprintf( ...
        %             'Median uncertainty: %.3f', ...
        %             M.MedianPixelUncertainty); ...
        %             ''; ...
        %             'PIXEL RECOMMENDATION'; ...
        %             M.PixelRecommendation}];
        %     else
        %
        %         textLines = ...
        %             [ ...
        %             textLines; ...
        %             { ...
        %             ''; ...
        %             'Pixel-level purity has not yet been assessed.'}];
        %     end
        %
        %     app.TextArea.Value = ...
        %         textLines;
        %
        % end


        function updateClusterInformationText(app)
            
            ids = ...
                app.SelectedClusterIDs;
            
            % =============================================================
            % NO CLUSTER SELECTED
            % =============================================================
            
            if isempty(ids)
                
                if app.PixelAssessmentReady
                    
                    app.updatePixelAssessmentSummary();
                    
                else
                    
                    overviewLines = { ...
                        'CLUSTER OVERVIEW'; ...
                        '================================================'; ...
                        ''; ...
                        ['Scan: ' app.CurrentScanName]; ...
                        sprintf( ...
                        'Active clusters: %d', ...
                        nnz([app.Clusters.Active])); ...
                        sprintf( ...
                        'Valid pixels: %d', ...
                        nnz(app.ValidMask2D)); ...
                        sprintf( ...
                        'PCA components used: %d', ...
                        app.NumPCUsed)};
                    
                    if isfinite(app.ClusterOverallMedianARI)
                        
                        overviewLines = ...
                            [ ...
                            overviewLines; ...
                            { ...
                            ''; ...
                            'K-MEANS STABILITY'; ...
                            sprintf( ...
                            'Independent runs: %d', ...
                            app.ClusterStabilityRuns); ...
                            sprintf( ...
                            'Overall median pairwise ARI: %.3f', ...
                            app.ClusterOverallMedianARI); ...
                            sprintf( ...
                            'Selected medoid run: %d', ...
                            app.ClusterSelectedRun); ...
                            sprintf( ...
                            'Selected seed: %d', ...
                            app.ClusterSelectedSeed); ...
                            sprintf( ...
                            'Medoid median ARI: %.3f', ...
                            app.ClusterMedoidMedianARI); ...
                            sprintf( ...
                            'Stability level: %s', ...
                            app.ClusterStabilityLevel)}];
                    end
                    
                    overviewLines = ...
                        [ ...
                        overviewLines; ...
                        { ...
                        ''; ...
                        'CLUSTER ASSIGNMENT QC'; ...
                        sprintf( ...
                        'Median centroid-assignment confidence: %.3f', ...
                        median(app.PixelConfidence, 'omitnan')); ...
                        ''; ...
                        'Select one cluster in the Tree for details.'}];
                    
                    app.TextArea.Value = ...
                        overviewLines;
                    
                end
                return
            end
            
            % =============================================================
            % MULTIPLE CLUSTERS SELECTED
            % =============================================================
            
            if numel(ids) > 1
                
                totalPixels = 0;
                gtCoveredPixels = 0;
                
                modelBackgroundPixels = 0;
                modelIntermediatePixels = 0;
                modelCellPixels = 0;
                
                for id = ids(:)'
                    
                    clusterIndex = ...
                        app.findClusterIndexByID(id);
                    
                    if isempty(clusterIndex)
                        continue
                    end
                    
                    C = ...
                        app.Clusters(clusterIndex);
                    
                    totalPixels = ...
                        totalPixels + ...
                        numel(C.PixelIdx);
                    
                    if app.PixelAssessmentReady && ...
                            isfield(C.Metrics, 'GTLabeledCount')
                        
                        gtCoveredPixels = ...
                            gtCoveredPixels + ...
                            C.Metrics.GTLabeledCount;
                        
                        modelBackgroundPixels = ...
                            modelBackgroundPixels + ...
                            round( ...
                            C.Metrics.ConfidentBackgroundFraction * ...
                            C.Metrics.ModelEvaluatedPixelCount);
                        
                        modelIntermediatePixels = ...
                            modelIntermediatePixels + ...
                            round( ...
                            C.Metrics.IntermediateFraction * ...
                            C.Metrics.ModelEvaluatedPixelCount);
                        
                        modelCellPixels = ...
                            modelCellPixels + ...
                            round( ...
                            C.Metrics.ConfidentCellFraction * ...
                            C.Metrics.ModelEvaluatedPixelCount);
                    end
                end
                
                modelTotal = ...
                    modelBackgroundPixels + ...
                    modelIntermediatePixels + ...
                    modelCellPixels;
                
                textLines = { ...
                    'MULTIPLE CLUSTERS SELECTED'; ...
                    '================================================'; ...
                    ''; ...
                    'SELECTION'; ...
                    sprintf( ...
                    'Clusters selected: %d', ...
                    numel(ids)); ...
                    sprintf( ...
                    'Combined cluster pixels: %d', ...
                    totalPixels)};
                
                if app.PixelAssessmentReady
                    
                    textLines = ...
                        [ ...
                        textLines; ...
                        { ...
                        ''; ...
                        'GROUND-TRUTH COVERAGE'; ...
                        sprintf( ...
                        'GT-covered pixels: %d / %d (%.2f%%)', ...
                        gtCoveredPixels, ...
                        totalPixels, ...
                        100 * gtCoveredPixels / max(1, totalPixels)); ...
                        ''; ...
                        'MODEL-SUPPORTED COMPOSITION'}];
                    
                    if modelTotal > 0
                        
                        textLines = ...
                            [ ...
                            textLines; ...
                            { ...
                            sprintf( ...
                            'Confident background: %.2f%%', ...
                            100 * modelBackgroundPixels / modelTotal); ...
                            sprintf( ...
                            'Intermediate / preserved: %.2f%%', ...
                            100 * modelIntermediatePixels / modelTotal); ...
                            sprintf( ...
                            'Confident cell: %.2f%%', ...
                            100 * modelCellPixels / modelTotal)}];
                    end
                    
                    textLines = ...
                        [ ...
                        textLines; ...
                        { ...
                        ''; ...
                        'INTERPRETATION'; ...
                        ['Select one cluster to inspect its direct GT overlap, ' ...
                        'membership distribution, evidence status, and action.']}];
                    
                else
                    
                    textLines = ...
                        [ ...
                        textLines; ...
                        { ...
                        ''; ...
                        'Pixel assessment has not been run.'}];
                end
                
                app.TextArea.Value = ...
                    textLines;
                
                return
            end
            
            % =============================================================
            % ONE CLUSTER SELECTED
            % =============================================================
            
            clusterIndex = ...
                app.findClusterIndexByID(ids(1));
            
            if isempty(clusterIndex)
                return
            end
            
            C = ...
                app.Clusters(clusterIndex);
            
            M = ...
                C.Metrics;
            
            textLines = { ...
                'CLUSTER EVIDENCE REPORT'; ...
                '================================================'; ...
                ''; ...
                'CLUSTER IDENTITY'; ...
                ['Name: ' C.Name]; ...
                sprintf('Cluster ID: %d', C.ID); ...
                sprintf('Parent ID: %d', C.ParentID); ...
                ['Manual layer: ' C.Class]; ...
                sprintf('Active: %d', C.Active); ...
                ''; ...
                'CLUSTER SIZE'; ...
                sprintf('Pixels: %d', numel(C.PixelIdx))};
            
            if isfield(M, 'PixelFraction')
                
                textLines = ...
                    [ ...
                    textLines; ...
                    { ...
                    sprintf( ...
                    'Fraction of valid map: %.2f%%', ...
                    100 * M.PixelFraction)}];
            end
            
            % =============================================================
            % CLUSTERING QC
            % =============================================================
            
            textLines = ...
                [ ...
                textLines; ...
                { ...
                ''; ...
                'CLUSTERING QC'; ...
                sprintf( ...
                'Median centroid-assignment confidence: %.3f', ...
                M.MedianConfidence); ...
                sprintf( ...
                'Low-confidence pixels: %.2f%%', ...
                100 * M.LowConfidenceFraction); ...
                sprintf( ...
                'Median PCA dispersion: %.4g', ...
                M.MedianPCADistance); ...
                sprintf( ...
                'Connected spatial components: %d', ...
                M.ConnectedComponents); ...
                sprintf( ...
                'Largest component fraction: %.2f%%', ...
                100 * M.LargestComponentFraction)}];
            
            % =============================================================
            % PIXEL EVIDENCE
            % =============================================================
            
            if app.PixelAssessmentReady && ...
                    isfield(M, 'EvidenceStatus')
                
                textLines = ...
                    [ ...
                    textLines; ...
                    { ...
                    ''; ...
                    'GROUND-TRUTH SUPPORT'; ...
                    sprintf( ...
                    'GT-covered pixels: %d / %d (%.2f%%)', ...
                    M.GTLabeledCount, ...
                    numel(C.PixelIdx), ...
                    100 * M.GTCoverage); ...
                    sprintf( ...
                    'Background-reference pixels: %d', ...
                    M.GTBackgroundCount); ...
                    sprintf( ...
                    'Cell-reference pixels: %d', ...
                    M.GTCellCount)}];
                
                if M.GTLabeledCount > 0
                    
                    textLines = ...
                        [ ...
                        textLines; ...
                        { ...
                        sprintf( ...
                        'Within GT overlap — Background: %.2f%%', ...
                        100 * M.GTBackgroundFractionWithinLabeled); ...
                        sprintf( ...
                        'Within GT overlap — Cell: %.2f%%', ...
                        100 * M.GTCellFractionWithinLabeled)}];
                else
                    
                    textLines = ...
                        [ ...
                        textLines; ...
                        { ...
                        'Within GT overlap: no cleaned reference pixels'}];
                end
                
                if M.HasSufficientGroundTruth
                    
                    gtStatus = ...
                        'Sufficient for evidence-supported interpretation';
                    
                else
                    
                    gtStatus = ...
                        'Insufficient for direct whole-cluster interpretation';
                end
                
                textLines = ...
                    [ ...
                    textLines; ...
                    { ...
                    ['GT support status: ' gtStatus]; ...
                    ''; ...
                    'MODEL-SUPPORTED COMPOSITION'; ...
                    sprintf( ...
                    'Confident background: %.2f%%', ...
                    100 * M.ConfidentBackgroundFraction); ...
                    sprintf( ...
                    'Intermediate / preserved: %.2f%%', ...
                    100 * M.IntermediateFraction); ...
                    sprintf( ...
                    'Confident cell: %.2f%%', ...
                    100 * M.ConfidentCellFraction); ...
                    ''; ...
                    'MEMBERSHIP DISTRIBUTION'; ...
                    sprintf( ...
                    'Mean P(cell): %.3f', ...
                    M.MeanCellMembership); ...
                    sprintf( ...
                    'Median P(cell): %.3f', ...
                    M.MedianCellMembership); ...
                    sprintf( ...
                    'P10–P90 P(cell): %.3f–%.3f', ...
                    M.P10CellMembership, ...
                    M.P90CellMembership); ...
                    ''; ...
                    'EVIDENCE-BASED INTERPRETATION'; ...
                    ['Status: ' M.EvidenceStatus]; ...
                    ['Basis: ' M.EvidenceBasis]; ...
                    ''; ...
                    'RECOMMENDED ACTION'; ...
                    M.RecommendedAction; ...
                    ''; ...
                    'IMPORTANT LIMITATION'; ...
                    ['Ground truth directly supports only the cleaned reference ' ...
                    'pixels counted above.']; ...
                    ['Composition outside those pixels is model-supported, ' ...
                    'not directly ground-truth-verified.']}];
                
            else
                
                textLines = ...
                    [ ...
                    textLines; ...
                    { ...
                    ''; ...
                    'PIXEL EVIDENCE'; ...
                    'Pixel assessment has not yet been run.'}];
            end
            
            app.TextArea.Value = ...
                textLines;
            
        end
        
        
        function rebuildLabelMapFromClusters(app)
            
            [ny, nx, ~] = ...
                size(app.RamanCube);
            
            labelMap = ...
                zeros(ny, nx);
            
            for i = 1:numel(app.Clusters)
                
                C = ...
                    app.Clusters(i);
                
                if ~C.Active || isempty(C.PixelIdx)
                    continue
                end
                
                labelMap(C.PixelIdx) = ...
                    C.ID;
            end
            
            app.ClusterLabelMap = ...
                labelMap;
            
            app.MetricLabels = ...
                app.getMetricLabelsFromCurrentClusters();
            
        end
        
        
        % function refreshClusterState(app)
        %
        %     app.rebuildLabelMapFromClusters();
        %
        %     app.recalculateClusterSpectra();
        %
        %     app.computeAllClusterMetrics([]);
        %
        %     app.populateClusterTree();
        %
        %     app.displayClusterMap();
        %
        %     app.displaySelectedClusterSpectra();
        %
        %     app.updateClusterInformationText();
        %
        %     app.UndoButton.Enable = ...
        %         app.onOff(~isempty(app.UndoStack));
        %
        %     app.RedoButton.Enable = ...
        %         app.onOff(~isempty(app.RedoStack));
        %
        % end
        
        
        % function refreshClusterState(app)
        %
        %     app.rebuildLabelMapFromClusters();
        %
        %     app.recalculateClusterSpectra();
        %
        %     app.computeAllClusterMetrics([]);
        %
        %     % Recalculate cluster evidence whenever the active cluster
        %     % composition changes after pixel assessment.
        %     if app.PixelAssessmentReady && ...
        %             ~isempty(app.CellMembershipMap)
        %
        %         app.computeClusterPixelEvidence();
        %     end
        %
        %     app.populateClusterTree();
        %
        %     app.displayClusterMap();
        %
        %     app.displaySelectedClusterSpectra();
        %
        %     if app.PixelAssessmentReady
        %
        %         app.displayPixelAssessmentResults();
        %     end
        %
        %     app.updateClusterInformationText();
        %
        %     app.UndoButton.Enable = ...
        %         app.onOff(~isempty(app.UndoStack));
        %
        %     app.RedoButton.Enable = ...
        %         app.onOff(~isempty(app.RedoStack));
        %
        % end
        
        
        function refreshClusterState(app)
            
            app.rebuildLabelMapFromClusters();
            
            app.recalculateClusterSpectra();
            
            app.computeAllClusterMetrics([]);
            
            if app.PixelAssessmentReady && ...
                    ~isempty(app.CellMembershipMap)
                
                app.computeClusterPixelEvidence();
            end
            
            % Update stale status without deleting historical assessments.
            for assessmentIndex = 1:numel(app.ComponentAssessments)
                
                app.ComponentAssessments(assessmentIndex) = ...
                    app.updateComponentAssessmentStaleState( ...
                    app.ComponentAssessments(assessmentIndex));
            end
            
            app.rebuildAllComponentLayerAggregates();
            
            app.populateClusterTree();
            
            app.displayClusterMap();
            
            % A Tree rebuild clears selected node handles. Operational cluster
            % selection is preserved through SelectedClusterIDs.
            app.displaySelectedClusterSpectra();
            
            selectedIDs = ...
                app.SelectedClusterIDs;
            
            componentIndex = [];
            
            if numel(selectedIDs) == 1
                
                componentIndex = ...
                    app.findLatestComponentAssessmentForCluster( ...
                    selectedIDs(1));
            end
            
            if ~isempty(componentIndex)
                
                app.CurrentComponentAssessmentIndex = ...
                    componentIndex;
                
                app.displayComponentAssessmentOnAxis4( ...
                    componentIndex);
                
                app.displayComponentAssessmentText( ...
                    componentIndex);
                
            else
                
                if app.PixelAssessmentReady
                    
                    app.displayPixelAssessmentResults();
                end
                
                app.updateClusterInformationText();
            end
            
            app.UndoButton.Enable = ...
                app.onOff(~isempty(app.UndoStack));
            
            app.RedoButton.Enable = ...
                app.onOff(~isempty(app.RedoStack));
            
        end
        
        
        function result = calculateLocalPeakRatio750over785( ...
                app, ...
                spectra, ...
                wavenumber)
            
            % =============================================================
            % LOCAL-BASELINE-CORRECTED 750 / 785 RATIO
            %
            % Input:
            %   spectra    : nSpectra x nWavenumbers
            %   wavenumber : nWavenumbers x 1
            %
            % Output fields:
            %   NumeratorIntensity
            %   DenominatorIntensity
            %   Ratio
            %   ValidRatio
            %   DenominatorFloor
            % =============================================================
            
            spectra = ...
                double(spectra);
            
            wavenumber = ...
                double(wavenumber(:));
            
            if isempty(spectra)
                
                result = struct( ...
                    'NumeratorIntensity', [], ...
                    'DenominatorIntensity', [], ...
                    'Ratio', [], ...
                    'ValidRatio', [], ...
                    'DenominatorFloor', NaN);
                
                return
            end
            
            if isvector(spectra)
                
                spectra = ...
                    reshape(spectra, 1, []);
            end
            
            if size(spectra, 2) ~= numel(wavenumber)
                
                error( ...
                    ['The number of spectrum channels does not match ' ...
                    'the supplied wavenumber axis.']);
            end
            
            % =============================================================
            % 1. DEFINE WINDOWS
            % =============================================================
            
            peak750Mask = ...
                wavenumber >= app.Ratio750Window(1) & ...
                wavenumber <= app.Ratio750Window(2);
            
            peak785Mask = ...
                wavenumber >= app.Ratio785Window(1) & ...
                wavenumber <= app.Ratio785Window(2);
            
            baselineLeftMask = ...
                wavenumber >= app.RatioBaselineLeftWindow(1) & ...
                wavenumber <= app.RatioBaselineLeftWindow(2);
            
            baselineRightMask = ...
                wavenumber >= app.RatioBaselineRightWindow(1) & ...
                wavenumber <= app.RatioBaselineRightWindow(2);
            
            requiredPointCounts = ...
                [ ...
                nnz(peak750Mask), ...
                nnz(peak785Mask), ...
                nnz(baselineLeftMask), ...
                nnz(baselineRightMask)];
            
            if any(requiredPointCounts < 2)
                
                error( ...
                    sprintf([ ...
                    'The Raman axis does not contain enough points for the ' ...
                    '750/785 ratio windows.\n\n' ...
                    '750 window points: %d\n' ...
                    '785 window points: %d\n' ...
                    'Left baseline points: %d\n' ...
                    'Right baseline points: %d'], ...
                    requiredPointCounts(1), ...
                    requiredPointCounts(2), ...
                    requiredPointCounts(3), ...
                    requiredPointCounts(4)));
            end
            
            % =============================================================
            % 2. REPLACE NON-FINITE VALUES ROW-BY-ROW
            % =============================================================
            
            for spectrumNumber = 1:size(spectra, 1)
                
                currentSpectrum = ...
                    spectra(spectrumNumber, :);
                
                finiteMask = ...
                    isfinite(currentSpectrum);
                
                if ~any(finiteMask)
                    
                    currentSpectrum(:) = ...
                        NaN;
                    
                else
                    
                    fillValue = ...
                        median( ...
                        currentSpectrum(finiteMask), ...
                        'omitnan');
                    
                    currentSpectrum(~finiteMask) = ...
                        fillValue;
                end
                
                spectra(spectrumNumber, :) = ...
                    currentSpectrum;
            end
            
            % =============================================================
            % 3. LOCAL LINEAR BASELINE
            %
            % One baseline point is estimated from each side window.
            % A straight line is interpolated between the two anchors.
            % =============================================================
            
            leftAnchorWN = ...
                mean( ...
                wavenumber(baselineLeftMask), ...
                'omitnan');
            
            rightAnchorWN = ...
                mean( ...
                wavenumber(baselineRightMask), ...
                'omitnan');
            
            leftAnchorIntensity = ...
                median( ...
                spectra(:, baselineLeftMask), ...
                2, ...
                'omitnan');
            
            rightAnchorIntensity = ...
                median( ...
                spectra(:, baselineRightMask), ...
                2, ...
                'omitnan');
            
            baselineSlope = ...
                (rightAnchorIntensity - leftAnchorIntensity) ./ ...
                max(rightAnchorWN - leftAnchorWN, eps);
            
            localBaseline = ...
                leftAnchorIntensity + ...
                baselineSlope .* ...
                (wavenumber(:).' - leftAnchorWN);
            
            correctedSpectra = ...
                spectra - localBaseline;
            
            % =============================================================
            % 4. INTEGRATE POSITIVE PEAK AREA
            %
            % Negative residuals are not treated as positive peak signal.
            % =============================================================
            
            corrected750 = ...
                correctedSpectra(:, peak750Mask);
            
            corrected785 = ...
                correctedSpectra(:, peak785Mask);
            
            corrected750 = ...
                max(corrected750, 0);
            
            corrected785 = ...
                max(corrected785, 0);
            
            wn750 = ...
                wavenumber(peak750Mask);
            
            wn785 = ...
                wavenumber(peak785Mask);
            
            numeratorIntensity = ...
                trapz( ...
                wn750, ...
                corrected750, ...
                2);
            
            denominatorIntensity = ...
                trapz( ...
                wn785, ...
                corrected785, ...
                2);
            
            % =============================================================
            % 5. DENOMINATOR QC
            %
            % A denominator near zero would create a falsely enormous ratio.
            % The floor is estimated from positive denominator values.
            % =============================================================
            
            positiveDenominator = ...
                denominatorIntensity( ...
                isfinite(denominatorIntensity) & ...
                denominatorIntensity > 0);
            
            if isempty(positiveDenominator)
                
                denominatorFloor = ...
                    Inf;
                
            else
                
                positiveMedian = ...
                    median( ...
                    positiveDenominator, ...
                    'omitnan');
                
                positiveP10 = ...
                    prctile( ...
                    positiveDenominator, ...
                    10);
                
                denominatorFloor = ...
                    max( ...
                    [ ...
                    eps, ...
                    0.05 * positiveMedian, ...
                    0.25 * positiveP10]);
            end
            
            validRatio = ...
                isfinite(numeratorIntensity) & ...
                isfinite(denominatorIntensity) & ...
                numeratorIntensity >= 0 & ...
                denominatorIntensity > denominatorFloor;
            
            ratio = ...
                nan(size(numeratorIntensity));
            
            ratio(validRatio) = ...
                numeratorIntensity(validRatio) ./ ...
                denominatorIntensity(validRatio);
            
            ratio( ...
                ~isfinite(ratio) | ...
                ratio <= 0) = ...
                NaN;
            
            validRatio = ...
                isfinite(ratio);
            
            % =============================================================
            % 6. OUTPUT
            % =============================================================
            
            result = struct();
            
            result.NumeratorIntensity = ...
                numeratorIntensity;
            
            result.DenominatorIntensity = ...
                denominatorIntensity;
            
            result.Ratio = ...
                ratio;
            
            result.ValidRatio = ...
                validRatio;
            
            result.DenominatorFloor = ...
                denominatorFloor;
            
        end
        
        
        function recalculateClusterSpectra(app)
            
            if isempty(app.RamanCube)
                return
            end
            
            [ny, nx, nWn] = ...
                size(app.RamanCube);
            
            X = ...
                reshape( ...
                app.RamanCube, ...
                ny * nx, ...
                nWn);
            
            for i = 1:numel(app.Clusters)
                
                pix = ...
                    app.Clusters(i).PixelIdx;
                
                if isempty(pix)
                    
                    app.Clusters(i).MeanSpectrum = ...
                        nan(1, nWn);
                    
                    app.Clusters(i).StdSpectrum = ...
                        nan(1, nWn);
                    
                    continue
                end
                
                spectra = ...
                    X(pix, :);
                
                app.Clusters(i).MeanSpectrum = ...
                    mean(spectra, 1, 'omitnan');
                
                app.Clusters(i).StdSpectrum = ...
                    std(spectra, 0, 1, 'omitnan');
            end
            
        end
        
        
        function state = onOff(app, tf) %#ok<MANU>
            
            if tf
                state = 'on';
            else
                state = 'off';
            end
            
        end


        function pushClusterHistory(app)
            
            snapshot = struct();
            
            snapshot.Clusters = ...
                app.Clusters;
            
            snapshot.ClusterLabelMap = ...
                app.ClusterLabelMap;
            
            snapshot.SelectedClusterIDs = ...
                app.SelectedClusterIDs;
            
            snapshot.NextClusterID = ...
                app.NextClusterID;
            
            snapshot.ClusterConfirmed = ...
                app.ClusterConfirmed;
            
            snapshot.ClusterClassLayers = ...
                app.ClusterClassLayers;
            
            app.UndoStack{end + 1} = ...
                snapshot;
            
            if numel(app.UndoStack) > app.MaxHistory
                
                app.UndoStack = ...
                    app.UndoStack( ...
                    end - app.MaxHistory + 1:end);
            end
            
            app.RedoStack = {};
            
            app.UndoButton.Enable = ...
                'on';
            
            app.RedoButton.Enable = ...
                'off';
            
        end
        
        
        function snapshot = captureCurrentClusterState(app)
            
            snapshot = struct();
            
            snapshot.Clusters = ...
                app.Clusters;
            
            snapshot.ClusterLabelMap = ...
                app.ClusterLabelMap;
            
            snapshot.SelectedClusterIDs = ...
                app.SelectedClusterIDs;
            
            snapshot.NextClusterID = ...
                app.NextClusterID;
            
            snapshot.ClusterConfirmed = ...
                app.ClusterConfirmed;
            
            snapshot.ClusterClassLayers = ...
                app.ClusterClassLayers;
            
        end
        
        function restoreClusterSnapshot(app, snapshot)
            
            app.Clusters = ...
                snapshot.Clusters;
            
            app.ClusterLabelMap = ...
                snapshot.ClusterLabelMap;
            
            app.SelectedClusterIDs = ...
                snapshot.SelectedClusterIDs;
            
            app.NextClusterID = ...
                snapshot.NextClusterID;
            
            app.ClusterConfirmed = ...
                snapshot.ClusterConfirmed;
            
            if isfield(snapshot, 'ClusterClassLayers') && ...
                    ~isempty(snapshot.ClusterClassLayers)
                
                app.ClusterClassLayers = ...
                    snapshot.ClusterClassLayers;
            end
            
            app.refreshClusterState();
            
        end
        
        
        function resetPixelAssessmentState(app)
            
            app.PixelAssessmentReady = false;
            app.PixelAssessmentConfirmed = false;
            
            app.ReferenceROIs = struct( ...
                'ID', {}, ...
                'Class', {}, ...
                'RawMask', {}, ...
                'CleanMask', {}, ...
                'OriginalPixelCount', {}, ...
                'ErodedPixelCount', {}, ...
                'UsablePixelCount', {}, ...
                'OutlierFraction', {}, ...
                'Status', {});
            
            app.NextReferenceROIID = 1;
            
            app.BackgroundReferenceMask2D = logical.empty;
            app.CellReferenceMask2D = logical.empty;
            
            app.BalancedTrainingPixelIdx = [];
            app.TrainingLabels = [];
            app.TrainingWeights = [];
            app.TrainingROIIDs = [];
            
            app.PixelModel = [];
            app.PixelModelType = '';
            
            app.PixelFeatureNames = {};
            app.PixelFeatureMu = [];
            app.PixelFeatureSigma = [];
            
            app.CellMembershipMap = [];
            app.PixelUncertaintyMap = [];
            app.PixelReliabilityMap = [];
            
            app.FinalCellMask2D = logical.empty;
            app.FinalBackgroundMask2D = logical.empty;
            app.FinalUncertainMask2D = logical.empty;
            app.FinalClassMap = [];
            
            app.PixelAssessmentMetrics = struct();
            
            app.resetImageAxis(app.UIAxes_2);
            title(app.UIAxes_2, 'Cell membership map');
            
            app.resetImageAxis(app.UIAxes_4);
            
            if isempty(app.ComponentAssessments)
                
                title( ...
                    app.UIAxes_4, ...
                    'Cluster/component reference assessment');
                
            elseif isfinite(app.CurrentComponentAssessmentIndex) && ...
                    app.CurrentComponentAssessmentIndex >= 1 && ...
                    app.CurrentComponentAssessmentIndex <= ...
                    numel(app.ComponentAssessments)
                
                app.displayComponentAssessmentOnAxis4( ...
                    app.CurrentComponentAssessmentIndex);
                
            else
                
                title( ...
                    app.UIAxes_4, ...
                    'Cluster/component reference assessment');
            end
            
        end
        
        
        function accepted = collectPixelReferenceROIs(app)
            
            accepted = false;
            
            % =============================================================
            % 1. VALIDATE INPUT
            % =============================================================
            
            if isempty(app.PreviewMap) || ...
                    isempty(app.ValidMask2D)
                
                uialert( ...
                    app.UIFigure, ...
                    'No trace map is available for ROI selection.', ...
                    'Missing trace map');
                
                return
            end
            
            % =============================================================
            % 2. LOCAL ROI STATE
            %
            % Changes are copied to app.ReferenceROIs only after Finish.
            % =============================================================
            
            localROIs = app.ReferenceROIs;
            nextID = app.NextReferenceROIID;
            
            roiGraphics = gobjects(0);
            
            % =============================================================
            % 3. CREATE ROI FIGURE
            % =============================================================
            
            roiFigure = uifigure( ...
                'Name', ...
                'Define pixel-level ground-truth references', ...
                'Position', ...
                [120 80 1050 720], ...
                'Color', ...
                [0.96 0.96 0.96]);
            
            % UserData controls when the waiting function is released.
            roiFigure.UserData = struct( ...
                'State', ...
                'waiting');
            
            mainGrid = uigridlayout(roiFigure);
            
            mainGrid.ColumnWidth = {'1x', 230};
            mainGrid.RowHeight = {'1x'};
            
            % =============================================================
            % 4. TRACE AXIS
            % =============================================================
            
            roiAxes = uiaxes(mainGrid);
            
            roiAxes.Layout.Row = 1;
            roiAxes.Layout.Column = 1;
            
            % =============================================================
            % 5. CONTROL PANEL
            % =============================================================
            
            controlPanel = uipanel( ...
                mainGrid, ...
                'Title', ...
                'Reference ROI controls');
            
            controlPanel.Layout.Row = 1;
            controlPanel.Layout.Column = 2;
            
            controlGrid = uigridlayout(controlPanel);
            
            controlGrid.ColumnWidth = {'1x'};
            
            controlGrid.RowHeight = { ...
                45, ...
                45, ...
                45, ...
                45, ...
                35, ...
                '1x', ...
                45, ...
                45};
            
            addBackgroundButton = uibutton( ...
                controlGrid, ...
                'push', ...
                'Text', ...
                'Add background ROI', ...
                'FontWeight', ...
                'bold', ...
                'ButtonPushedFcn', ...
                @addBackgroundROI);
            
            addBackgroundButton.Layout.Row = 1;
            
            addCellButton = uibutton( ...
                controlGrid, ...
                'push', ...
                'Text', ...
                'Add cell ROI', ...
                'FontWeight', ...
                'bold', ...
                'ButtonPushedFcn', ...
                @addCellROI);
            
            addCellButton.Layout.Row = 2;
            
            removeLastButton = uibutton( ...
                controlGrid, ...
                'push', ...
                'Text', ...
                'Remove last ROI', ...
                'ButtonPushedFcn', ...
                @removeLastROI);
            
            removeLastButton.Layout.Row = 3;
            
            clearAllButton = uibutton( ...
                controlGrid, ...
                'push', ...
                'Text', ...
                'Clear all ROIs', ...
                'ButtonPushedFcn', ...
                @clearAllROIs);
            
            clearAllButton.Layout.Row = 4;
            
            instructionLabel = uilabel( ...
                controlGrid, ...
                'Text', ...
                'Double-click to finish each polygon.', ...
                'HorizontalAlignment', ...
                'center', ...
                'FontAngle', ...
                'italic');
            
            instructionLabel.Layout.Row = 5;
            
            statusArea = uitextarea( ...
                controlGrid, ...
                'Editable', ...
                'off');
            
            statusArea.Layout.Row = 6;
            
            finishButton = uibutton( ...
                controlGrid, ...
                'push', ...
                'Text', ...
                'Finish ROI selection', ...
                'FontWeight', ...
                'bold', ...
                'ButtonPushedFcn', ...
                @finishSelection);
            
            finishButton.Layout.Row = 7;
            
            cancelButton = uibutton( ...
                controlGrid, ...
                'push', ...
                'Text', ...
                'Cancel', ...
                'ButtonPushedFcn', ...
                @cancelSelection);
            
            cancelButton.Layout.Row = 8;
            
            roiFigure.CloseRequestFcn = ...
                @cancelSelection;
            
            % =============================================================
            % 6. DRAW TRACE ONCE
            % =============================================================
            
            drawTraceBase();
            
            % Recreate ROIs from a previous assessment, when continuing.
            recreateExistingROIs();
            
            updateStatus();
            
            % =============================================================
            % 7. WAIT ONLY FOR EXPLICIT FINISH/CANCEL
            %
            % Unlike uiwait/uiresume, this waits for UserData to change.
            % Completing drawpolygon does not release this wait.
            % =============================================================
            
            waitfor( ...
                roiFigure, ...
                'UserData');
            
            if isvalid(roiFigure)
                
                finalState = ...
                    roiFigure.UserData;
                
                if isstruct(finalState) && ...
                        isfield(finalState, 'State') && ...
                        strcmp(finalState.State, 'finished')
                    
                    accepted = true;
                    
                else
                    
                    accepted = false;
                end
                
                delete(roiFigure);
            end
            
            % =============================================================
            % NESTED CALLBACKS
            % =============================================================
            
            function addBackgroundROI(~, ~)
                
                addROIOfClass( ...
                    'Background', ...
                    [0.15 0.55 1.00]);
                
            end
            
            
            function addCellROI(~, ~)
                
                addROIOfClass( ...
                    'Cell', ...
                    [0.10 0.90 0.25]);
                
            end
            
            
            function addROIOfClass(className, roiColor)
                
                if ~isvalid(roiFigure) || ...
                        ~isvalid(roiAxes)
                    
                    return
                end
                
                drawnow;
                
                % =========================================================
                % USER DRAWS ONE POLYGON
                % =========================================================
                
                polygon = drawpolygon( ...
                    roiAxes, ...
                    'Color', ...
                    roiColor, ...
                    'LineWidth', ...
                    2, ...
                    'FaceAlpha', ...
                    0.12);
                
                if isempty(polygon) || ...
                        ~isvalid(polygon)
                    
                    return
                end
                
                polygonMask = ...
                    logical(createMask(polygon));
                
                originalPixelCount = ...
                    nnz(polygonMask);
                
                usablePreviewMask = ...
                    polygonMask & ...
                    app.ValidMask2D & ...
                    ~app.ArtifactMask2D;
                
                usablePixelCount = ...
                    nnz(usablePreviewMask);
                
                % =========================================================
                % BASIC ROI VALIDATION
                % =========================================================
                
                if originalPixelCount < 3
                    
                    delete(polygon);
                    
                    uialert( ...
                        roiFigure, ...
                        'The selected polygon contains fewer than 3 pixels.', ...
                        'ROI too small');
                    
                    return
                end
                
                if usablePixelCount < 3
                    
                    delete(polygon);
                    
                    uialert( ...
                        roiFigure, ...
                        { ...
                        'This ROI contains fewer than 3 usable pixels.'; ...
                        ''; ...
                        'The selected region may be outside ValidMask2D'; ...
                        'or inside the artifact mask.'}, ...
                        'ROI outside usable region');
                    
                    return
                end
                
                % =========================================================
                % STORE ROI
                %
                % Preserve the complete polygon mask.
                % Do not clip RawMask here.
                % =========================================================
                
                R = struct();
                
                R.ID = ...
                    nextID;
                
                R.Class = ...
                    className;
                
                R.RawMask = ...
                    polygonMask;
                
                R.CleanMask = ...
                    false(size(polygonMask));
                
                R.OriginalPixelCount = ...
                    originalPixelCount;
                
                R.ErodedPixelCount = ...
                    0;
                
                R.UsablePixelCount = ...
                    0;
                
                R.OutlierFraction = ...
                    NaN;
                
                R.Status = ...
                    'Not assessed';
                
                localROIs(end + 1) = ...
                    R; %#ok<AGROW>
                
                % =========================================================
                % KEEP POLYGON VISIBLE
                %
                % Do not delete it and do not redraw/reset the axis.
                % =========================================================
                
                polygon.Tag = ...
                    sprintf( ...
                    'ReferenceROI_%d', ...
                    nextID);
                
                try
                    
                    polygon.Label = ...
                        sprintf( ...
                        '%s %d', ...
                        className(1), ...
                        nextID);
                    
                    polygon.LabelVisible = ...
                        'on';
                    
                catch
                end
                
                % Lock completed ROI so its mask cannot silently change.
                try
                    
                    polygon.InteractionsAllowed = ...
                        'none';
                    
                catch
                end
                
                roiGraphics(end + 1) = ...
                    polygon; %#ok<AGROW>
                
                nextID = ...
                    nextID + 1;
                
                updateStatus();
                
            end
            
            
            function removeLastROI(~, ~)
                
                if isempty(localROIs)
                    return
                end
                
                localROIs(end) = [];
                
                if ~isempty(roiGraphics)
                    
                    lastGraphic = ...
                        roiGraphics(end);
                    
                    if isvalid(lastGraphic)
                        delete(lastGraphic);
                    end
                    
                    roiGraphics(end) = [];
                end
                
                updateStatus();
                
            end
            
            
            function clearAllROIs(~, ~)
                
                if isempty(localROIs)
                    return
                end
                
                choice = uiconfirm( ...
                    roiFigure, ...
                    'Remove every background and cell ROI?', ...
                    'Clear reference ROIs', ...
                    'Options', ...
                    {'Clear', 'Cancel'}, ...
                    'DefaultOption', ...
                    'Cancel', ...
                    'CancelOption', ...
                    'Cancel');
                
                if ~strcmp(choice, 'Clear')
                    return
                end
                
                for graphicIndex = 1:numel(roiGraphics)
                    
                    if isvalid(roiGraphics(graphicIndex))
                        delete(roiGraphics(graphicIndex));
                    end
                end
                
                roiGraphics = ...
                    gobjects(0);
                
                localROIs = ...
                    localROIs([]);
                
                updateStatus();
                
            end
            
            
            function finishSelection(~, ~)
                
                classes = ...
                    {localROIs.Class};
                
                numberBackground = ...
                    nnz(strcmp(classes, 'Background'));
                
                numberCell = ...
                    nnz(strcmp(classes, 'Cell'));
                
                % =========================================================
                % BOTH CLASSES REQUIRED BY CURRENT BINARY MODEL
                % =========================================================
                
                if numberBackground < 1 || ...
                        numberCell < 1
                    
                    if numberBackground < 1 && ...
                            numberCell < 1
                        
                        missingMessage = ...
                            ['No Background ROI and no Cell ROI ' ...
                            'were selected.'];
                        
                    elseif numberBackground < 1
                        
                        missingMessage = ...
                            ['No Background ROI was selected. ' ...
                            'The binary Cell-versus-Background model ' ...
                            'requires both classes.'];
                        
                    else
                        
                        missingMessage = ...
                            ['No Cell ROI was selected. ' ...
                            'The binary Cell-versus-Background model ' ...
                            'requires both classes.'];
                    end
                    
                    uialert( ...
                        roiFigure, ...
                        { ...
                        missingMessage; ...
                        ''; ...
                        'Add at least one ROI for the missing class.'}, ...
                        'Both classes required');
                    
                    return
                end
                
                % =========================================================
                % WARN WHEN ROI DIVERSITY IS LOW
                % =========================================================
                
                if numberBackground < 3 || ...
                        numberCell < 3
                    
                    choice = uiconfirm( ...
                        roiFigure, ...
                        { ...
                        'Fewer than 3 ROIs were selected for one or both classes.'; ...
                        ''; ...
                        sprintf( ...
                        'Background ROIs: %d', ...
                        numberBackground); ...
                        sprintf( ...
                        'Cell ROIs: %d', ...
                        numberCell); ...
                        ''; ...
                        'Three or more independent ROIs per class are recommended.'; ...
                        'Continue anyway?'}, ...
                        'Limited ROI diversity', ...
                        'Options', ...
                        {'Continue', 'Add more ROIs'}, ...
                        'DefaultOption', ...
                        'Add more ROIs', ...
                        'CancelOption', ...
                        'Add more ROIs');
                    
                    if ~strcmp(choice, 'Continue')
                        return
                    end
                end
                
                % =========================================================
                % COMMIT ROI DATA TO APP
                % =========================================================
                
                app.ReferenceROIs = ...
                    localROIs;
                
                app.NextReferenceROIID = ...
                    nextID;
                
                accepted = ...
                    true;
                
                roiFigure.UserData = struct( ...
                    'State', ...
                    'finished');
                
            end
            
            
            function cancelSelection(~, ~)
                
                accepted = false;
                
                if isvalid(roiFigure)
                    
                    roiFigure.UserData = struct( ...
                        'State', ...
                        'cancelled');
                end
                
            end
            
            
            function drawTraceBase()
                
                % This function is called only once.
                % It must not be called after every new ROI.
                
                cla(roiAxes, 'reset');
                
                traceHandle = imagesc( ...
                    roiAxes, ...
                    app.PreviewMap);
                
                traceHandle.HitTest = ...
                    'off';
                
                axis(roiAxes, 'image');
                
                roiAxes.YDir = ...
                    'normal';
                
                roiAxes.Box = ...
                    'on';
                
                roiAxes.XTick = [];
                roiAxes.YTick = [];
                
                colormap(roiAxes, gray(256));
                
                traceValues = ...
                    app.PreviewMap( ...
                    isfinite(app.PreviewMap));
                
                if ~isempty(traceValues)
                    
                    lowValue = ...
                        prctile(traceValues, 2);
                    
                    highValue = ...
                        prctile(traceValues, 98);
                    
                    if isfinite(lowValue) && ...
                            isfinite(highValue) && ...
                            lowValue < highValue
                        
                        clim( ...
                            roiAxes, ...
                            [lowValue highValue]);
                    end
                end
                
                hold(roiAxes, 'on');
                
                % Display current valid-region boundary.
                contour( ...
                    roiAxes, ...
                    app.ValidMask2D, ...
                    [0.5 0.5], ...
                    'Color', ...
                    [1.00 0.85 0.10], ...
                    'LineWidth', ...
                    0.8, ...
                    'LineStyle', ...
                    '--', ...
                    'HitTest', ...
                    'off');
                
                hold(roiAxes, 'off');
                
                title( ...
                    roiAxes, ...
                    { ...
                    app.CurrentScanName, ...
                    'Blue = Background | Green = Cell', ...
                    'Yellow dashed line = usable ValidMask2D'}, ...
                    'Interpreter', ...
                    'none');
                
            end
            
            
            function recreateExistingROIs()
                
                if isempty(localROIs)
                    return
                end
                
                for localIndex = 1:numel(localROIs)
                    
                    currentROI = ...
                        localROIs(localIndex);
                    
                    if strcmp( ...
                            currentROI.Class, ...
                            'Background')
                        
                        roiColor = ...
                            [0.15 0.55 1.00];
                        
                    else
                        
                        roiColor = ...
                            [0.10 0.90 0.25];
                    end
                    
                    boundaries = ...
                        bwboundaries( ...
                        currentROI.RawMask, ...
                        8, ...
                        'noholes');
                    
                    if isempty(boundaries)
                        continue
                    end
                    
                    boundaryLengths = ...
                        cellfun( ...
                        @(boundary) size(boundary, 1), ...
                        boundaries);
                    
                    [~, largestBoundaryIndex] = ...
                        max(boundaryLengths);
                    
                    boundary = ...
                        boundaries{largestBoundaryIndex};
                    
                    polygonPosition = ...
                        [ ...
                        boundary(:, 2), ...
                        boundary(:, 1)];
                    
                    polygon = drawpolygon( ...
                        roiAxes, ...
                        'Position', ...
                        polygonPosition, ...
                        'Color', ...
                        roiColor, ...
                        'LineWidth', ...
                        2, ...
                        'FaceAlpha', ...
                        0.12);
                    
                    try
                        
                        polygon.Label = ...
                            sprintf( ...
                            '%s %d', ...
                            currentROI.Class(1), ...
                            currentROI.ID);
                        
                        polygon.LabelVisible = ...
                            'on';
                        
                    catch
                    end
                    
                    try
                        
                        polygon.InteractionsAllowed = ...
                            'none';
                        
                    catch
                    end
                    
                    roiGraphics(end + 1) = ...
                        polygon; %#ok<AGROW>
                end
                
            end
            
            
            function updateStatus()
                
                if isempty(localROIs)
                    
                    classes = {};
                    
                else
                    
                    classes = ...
                        {localROIs.Class};
                end
                
                numberBackground = ...
                    nnz(strcmp(classes, 'Background'));
                
                numberCell = ...
                    nnz(strcmp(classes, 'Cell'));
                
                backgroundUsablePixels = 0;
                cellUsablePixels = 0;
                
                for localIndex = 1:numel(localROIs)
                    
                    previewUsableMask = ...
                        localROIs(localIndex).RawMask & ...
                        app.ValidMask2D & ...
                        ~app.ArtifactMask2D;
                    
                    if strcmp( ...
                            localROIs(localIndex).Class, ...
                            'Background')
                        
                        backgroundUsablePixels = ...
                            backgroundUsablePixels + ...
                            nnz(previewUsableMask);
                        
                    elseif strcmp( ...
                            localROIs(localIndex).Class, ...
                            'Cell')
                        
                        cellUsablePixels = ...
                            cellUsablePixels + ...
                            nnz(previewUsableMask);
                    end
                end
                
                statusArea.Value = { ...
                    'REFERENCE ROI STATUS'; ...
                    '============================'; ...
                    ''; ...
                    sprintf( ...
                    'Background ROIs: %d', ...
                    numberBackground); ...
                    sprintf( ...
                    'Cell ROIs: %d', ...
                    numberCell); ...
                    sprintf( ...
                    'Total ROIs: %d', ...
                    numel(localROIs)); ...
                    ''; ...
                    sprintf( ...
                    'Background usable pixels: %d', ...
                    backgroundUsablePixels); ...
                    sprintf( ...
                    'Cell usable pixels: %d', ...
                    cellUsablePixels); ...
                    ''; ...
                    'Recommended:'; ...
                    '3-8 independent ROIs per class.'; ...
                    'Double-click to finish each polygon.'};
                
            end
            
        end
        
        
        function assignSelectedClusters(app)
            
            ids = ...
                app.SelectedClusterIDs;
            
            if isempty(ids)
                
                uialert( ...
                    app.UIFigure, ...
                    'Select one or more active clusters first.', ...
                    'No cluster selected');
                
                return
            end
            
            % =============================================================
            % 1. ENSURE DEFAULT LAYERS EXIST
            % =============================================================
            
            defaultLayers = { ...
                'Unassigned', ...
                'Cell candidate', ...
                'Background candidate', ...
                'Mixed / uncertain', ...
                'Artifact candidate'};
            
            app.ClusterClassLayers = ...
                unique( ...
                [ ...
                defaultLayers, ...
                app.ClusterClassLayers], ...
                'stable');
            
            selectableLayers = ...
                app.ClusterClassLayers;
            
            displayOptions = ...
                [ ...
                selectableLayers, ...
                {'Create new layer...'}];
            
            % =============================================================
            % 2. SELECT OR CREATE LAYER
            % =============================================================
            
            [selection, ok] = ...
                listdlg( ...
                'PromptString', ...
                { ...
                'Assign selected cluster(s) to a layer:'; ...
                'Choose Create new layer... for a custom category.'}, ...
                'SelectionMode', ...
                'single', ...
                'ListString', ...
                displayOptions, ...
                'InitialValue', ...
                1, ...
                'ListSize', ...
                [330 230]);
            
            if ~ok || ...
                    isempty(selection)
                
                return
            end
            
            selectedOption = ...
                displayOptions{selection};
            
            if strcmp(selectedOption, 'Create new layer...')
                
                answer = ...
                    inputdlg( ...
                    { ...
                    'New layer name:'; ...
                    'Examples: Artifact candidate, Edge region, Low-SNR, Debris'}, ...
                    'Create cluster layer', ...
                    [1 55; 1 55], ...
                    {'', ''});
                
                if isempty(answer)
                    return
                end
                
                selectedClass = ...
                    strtrim(answer{1});
                
                if isempty(selectedClass)
                    
                    uialert( ...
                        app.UIFigure, ...
                        'The new layer name cannot be empty.', ...
                        'Invalid layer name');
                    
                    return
                end
                
                forbiddenNames = { ...
                    'Split parent', ...
                    'Merged source', ...
                    'Excluded'};
                
                if any(strcmpi( ...
                        selectedClass, ...
                        forbiddenNames))
                    
                    uialert( ...
                        app.UIFigure, ...
                        'That name is reserved for cluster history states.', ...
                        'Reserved layer name');
                    
                    return
                end
                
                existingMatch = ...
                    find( ...
                    strcmpi( ...
                    app.ClusterClassLayers, ...
                    selectedClass), ...
                    1);
                
                if isempty(existingMatch)
                    
                    app.ClusterClassLayers{end + 1} = ...
                        selectedClass;
                    
                else
                    
                    selectedClass = ...
                        app.ClusterClassLayers{existingMatch};
                end
                
            else
                
                selectedClass = ...
                    selectedOption;
            end
            
            % =============================================================
            % 3. ASSIGN SELECTED CLUSTERS
            % =============================================================
            
            app.pushClusterHistory();
            
            changedCount = ...
                0;
            
            for id = ids(:)'
                
                clusterIndex = ...
                    app.findClusterIndexByID(id);
                
                if isempty(clusterIndex) || ...
                        ~app.Clusters(clusterIndex).Active
                    
                    continue
                end
                
                app.Clusters(clusterIndex).Class = ...
                    selectedClass;
                
                changedCount = ...
                    changedCount + 1;
            end
            
            app.ClusterConfirmed = ...
                false;
            
            app.refreshClusterState();
            
            app.TextArea.Value = { ...
                'CLUSTER ASSIGNMENT UPDATED'; ...
                '================================'; ...
                ''; ...
                sprintf('Clusters changed: %d', ...
                changedCount); ...
                ['Assigned layer: ' selectedClass]; ...
                ''; ...
                sprintf('Available layers: %d', ...
                numel(app.ClusterClassLayers)); ...
                ''; ...
                'Cluster pixels were not changed.'};
            
        end


        function colors = makeChildColors(app, parentColor) %#ok<MANU>
            
            parentColor = ...
                double(parentColor(:).');
            
            colors = zeros(2, 3);
            
            colors(1, :) = ...
                min(1, 0.75 * parentColor + 0.25);
            
            colors(2, :) = ...
                max(0, 0.75 * parentColor);
            
            if norm(colors(1, :) - colors(2, :)) < 0.10
                
                colors(1, :) = ...
                    min(1, parentColor + [0.20 -0.05 0.10]);
                
                colors(2, :) = ...
                    max(0, parentColor + [-0.10 0.15 -0.05]);
            end
            
        end


        function mergeSelectedClusters(app)
            
            ids = ...
                app.SelectedClusterIDs;
            
            if numel(ids) < 2
                
                uialert( ...
                    app.UIFigure, ...
                    'Select at least two active clusters to merge.', ...
                    'Merge requires multiple clusters');
                
                return
            end
            
            selectedIndices = [];
            
            for id = ids(:)'
                
                idx = ...
                    app.findClusterIndexByID(id);
                
                if ~isempty(idx) && ...
                        app.Clusters(idx).Active
                    
                    selectedIndices(end + 1) = ...
                        idx; %#ok<AGROW>
                end
            end
            
            selectedIndices = ...
                unique(selectedIndices);
            
            if numel(selectedIndices) < 2
                
                uialert( ...
                    app.UIFigure, ...
                    'Fewer than two selected clusters are active.', ...
                    'Cannot merge');
                
                return
            end
            
            pixelCount = ...
                sum( ...
                arrayfun( ...
                @(i) numel(app.Clusters(i).PixelIdx), ...
                selectedIndices));
            
            choice = ...
                uiconfirm( ...
                app.UIFigure, ...
                { ...
                sprintf('Merge %d clusters?', ...
                numel(selectedIndices)); ...
                sprintf('Combined pixels: %d', pixelCount); ...
                ''; ...
                'The source clusters will remain in history as inactive states.'}, ...
                'Merge clusters', ...
                'Options', ...
                {'Merge', 'Cancel'}, ...
                'DefaultOption', ...
                'Merge', ...
                'CancelOption', ...
                'Cancel');
            
            if ~strcmp(choice, 'Merge')
                return
            end
            
            app.pushClusterHistory();
            
            mergedPixels = [];
            
            selectedClasses = ...
                cell(1, numel(selectedIndices));
            
            selectedColors = ...
                zeros(numel(selectedIndices), 3);
            
            for n = 1:numel(selectedIndices)
                
                idx = ...
                    selectedIndices(n);
                
                mergedPixels = ...
                    [ ...
                    mergedPixels; ...
                    app.Clusters(idx).PixelIdx(:)]; %#ok<AGROW>
                
                selectedClasses{n} = ...
                    app.Clusters(idx).Class;
                
                selectedColors(n, :) = ...
                    app.Clusters(idx).Color;
                
                app.Clusters(idx).Active = ...
                    false;
                
                app.Clusters(idx).Class = ...
                    'Merged source';
            end
            
            mergedPixels = ...
                unique(mergedPixels);
            
            if numel(unique(selectedClasses)) == 1
                
                mergedClass = ...
                    selectedClasses{1};
                
            else
                
                mergedClass = ...
                    'Mixed / uncertain';
            end
            
            newCluster = struct();
            
            newCluster.ID = ...
                app.NextClusterID;
            
            newCluster.Name = ...
                sprintf( ...
                'Merged cluster %d', ...
                newCluster.ID);
            
            newCluster.ParentID = ...
                0;
            
            newCluster.PixelIdx = ...
                double(mergedPixels(:));
            
            newCluster.Class = ...
                mergedClass;
            
            newCluster.Active = ...
                true;
            
            newCluster.MeanSpectrum = [];
            newCluster.StdSpectrum = [];
            
            newCluster.Color = ...
                mean(selectedColors, 1);
            
            newCluster.Color = ...
                max(0, min(1, newCluster.Color));
            
            newCluster.Metrics = ...
                struct();
            
            app.Clusters(end + 1) = ...
                newCluster;
            
            app.NextClusterID = ...
                app.NextClusterID + 1;
            
            app.SelectedClusterIDs = ...
                newCluster.ID;
            
            app.ClusterConfirmed = false;
            
            app.refreshClusterState();
            
            app.TextArea.Value = { ...
                'CLUSTER MERGE COMPLETED'; ...
                '================================'; ...
                ''; ...
                sprintf('Source clusters: %d', ...
                numel(selectedIndices)); ...
                sprintf('New cluster ID: %d', ...
                newCluster.ID); ...
                sprintf('Merged pixels: %d', ...
                numel(mergedPixels)); ...
                ['Assigned class: ' mergedClass]};
            
        end
        
        
        function excludeSelectedClusters(app)
            
            ids = ...
                app.SelectedClusterIDs;
            
            if isempty(ids)
                
                uialert( ...
                    app.UIFigure, ...
                    'Select one or more active clusters first.', ...
                    'No cluster selected');
                
                return
            end
            
            activeIndices = [];
            
            for id = ids(:)'
                
                idx = ...
                    app.findClusterIndexByID(id);
                
                if ~isempty(idx) && ...
                        app.Clusters(idx).Active
                    
                    activeIndices(end + 1) = ...
                        idx; %#ok<AGROW>
                end
            end
            
            activeIndices = ...
                unique(activeIndices);
            
            if isempty(activeIndices)
                return
            end
            
            totalPixels = ...
                sum( ...
                arrayfun( ...
                @(i) numel(app.Clusters(i).PixelIdx), ...
                activeIndices));
            
            choice = ...
                uiconfirm( ...
                app.UIFigure, ...
                { ...
                sprintf('Exclude %d selected cluster(s)?', ...
                numel(activeIndices)); ...
                sprintf('Pixels excluded from the active cluster map: %d', ...
                totalPixels); ...
                ''; ...
                'The Raman cube will not be modified.'; ...
                'Undo can restore the cluster state.'}, ...
                'Exclude clusters', ...
                'Options', ...
                {'Exclude', 'Cancel'}, ...
                'DefaultOption', ...
                'Cancel', ...
                'CancelOption', ...
                'Cancel');
            
            if ~strcmp(choice, 'Exclude')
                return
            end
            
            app.pushClusterHistory();
            
            for idx = activeIndices
                
                app.Clusters(idx).Active = ...
                    false;
                
                app.Clusters(idx).Class = ...
                    'Excluded';
            end
            
            app.SelectedClusterIDs = [];
            
            app.ClusterConfirmed = false;
            
            app.refreshClusterState();
            
            app.TextArea.Value = { ...
                'CLUSTER EXCLUSION COMPLETED'; ...
                '================================'; ...
                ''; ...
                sprintf('Clusters excluded: %d', ...
                numel(activeIndices)); ...
                sprintf('Pixels excluded: %d', ...
                totalPixels); ...
                ''; ...
                'The Raman cube was not modified.'};
            
        end


        function changeSelectedClusterColor(app)
            
            ids = ...
                app.SelectedClusterIDs;
            
            if numel(ids) ~= 1
                
                uialert( ...
                    app.UIFigure, ...
                    'Select exactly one active cluster to change its color.', ...
                    'Select one cluster');
                
                return
            end
            
            idx = ...
                app.findClusterIndexByID(ids(1));
            
            if isempty(idx) || ...
                    ~app.Clusters(idx).Active
                
                return
            end
            
            oldColor = ...
                app.Clusters(idx).Color;
            
            newColor = ...
                uisetcolor( ...
                oldColor, ...
                ['Color for ' app.Clusters(idx).Name]);
            
            if isequal(newColor, 0)
                return
            end
            
            app.pushClusterHistory();
            
            app.Clusters(idx).Color = ...
                double(newColor);
            
            app.refreshClusterState();
            
        end

        function colors = makePaletteColors(app, paletteName, numberOfColors) %#ok<INUSD>
            
            numberOfColors = ...
                max(1, round(numberOfColors));
            
            switch lower(strtrim(paletteName))
                
                case lower('High contrast')
                    
                    colors = ...
                        hsv(max(numberOfColors, 2));
                    
                case lower('Soft')
                    
                    colors = ...
                        lines(max(numberOfColors, 2));
                    
                    colors = ...
                        0.65 .* colors + 0.35;
                    
                case {lower('Hot'), lower('Dark background')}
                    
                    colors = ...
                        hot(max(numberOfColors, 2));
                    
                case lower('Turbo')
                    
                    colors = ...
                        turbo(max(numberOfColors, 2));
                    
                case lower('Parula')
                    
                    colors = ...
                        parula(max(numberOfColors, 2));
                    
                case lower('Jet')
                    
                    colors = ...
                        jet(max(numberOfColors, 2));
                    
                case lower('HSV')
                    
                    colors = ...
                        hsv(max(numberOfColors, 2));
                case lower('Lines')
                    
                    colors = ...
                        lines(max(numberOfColors, 2));
                    
                otherwise
                    
                    colors = ...
                        lines(max(numberOfColors, 2));
            end
            
            colors = ...
                colors(1:numberOfColors, :);
            
        end
        
        
        function displaySelectedMode(app)
            
            modeValue = ...
                strtrim( ...
                char(string(app.DisplayDropDown.Value)));
            
            switch lower(modeValue)
                
                % =========================================================
                % CLUSTER LEVEL
                % =========================================================
                
                case lower('Cluster map')
                    
                    if ~app.ClusterReady
                        
                        app.displayInitialClusterInput();
                        
                    else
                        
                        app.displayClusterMap();
                    end
                    
                case lower('Assigned class map')
                    
                    app.requireClusterResultForDisplay();
                    
                    app.displayAssignedClassMap();
                    
                case lower('Cluster confidence')
                    
                    app.requireClusterResultForDisplay();
                    
                    app.displayConfidenceMap();
                    
                case lower('Cluster pixel count')
                    
                    app.requireClusterResultForDisplay();
                    
                    app.displayClusterPixelCountMap();
                    
                case lower('Selected spectra')
                    
                    app.requireClusterResultForDisplay();
                    
                    selectedGroups = ...
                        app.getSelectedSpectralGroupsFromTree();
                    
                    if isempty(selectedGroups)
                        
                        app.displaySelectedClusterSpectra();
                        
                    else
                        
                        app.displaySelectedSpectralGroups( ...
                            selectedGroups);
                    end
                    
                case lower('All active spectra')
                    
                    app.requireClusterResultForDisplay();
                    
                    app.displayAllClusterMeans();
                    
                    % =========================================================
                    % PIXEL LEVEL
                    % =========================================================
                    
                case lower('Pixel assessment')
                    
                    app.requirePixelAssessmentForDisplay();
                    
                    app.displayPixelAssessmentResults();
                    
                case lower('Cell mask')
                    
                    app.requirePixelAssessmentForDisplay();
                    
                    app.displayBinaryPixelMask( ...
                        app.FinalCellMask2D, ...
                        'Final confident-cell mask', ...
                        app.PurityCellColor);
                    
                case lower('Background mask')
                    
                    app.requirePixelAssessmentForDisplay();
                    
                    app.displayBinaryPixelMask( ...
                        app.FinalBackgroundMask2D, ...
                        'Final confident-background mask', ...
                        app.PurityBackgroundColor);
                    
                case lower('Intermediate mask')
                    
                    app.requirePixelAssessmentForDisplay();
                    
                    app.displayBinaryPixelMask( ...
                        app.FinalUncertainMask2D, ...
                        'Final intermediate / uncertain mask', ...
                        app.PurityIntermediateColor);
                    
                    % =========================================================
                    % REFERENCE COMPONENT
                    % =========================================================
                    
                case lower('Reference ratio - continuous')
                    
                    assessmentIndex = ...
                        app.getCurrentDisplayComponentAssessmentIndex();
                    
                    if isempty(assessmentIndex)
                        
                        uialert( ...
                            app.UIFigure, ...
                            ['Select one reference-component assessment ' ...
                            'in the Tree first.'], ...
                            'No component assessment');
                        
                        return
                    end
                    
                    app.CurrentComponentAssessmentIndex = ...
                        assessmentIndex;
                    
                    app.displayComponentAssessmentOnAxis4( ...
                        assessmentIndex);
                    
                    app.displayComponentAssessmentText( ...
                        assessmentIndex);
                    
                case lower('Reference ratio - 3 levels')
                    
                    assessmentIndex = ...
                        app.getCurrentDisplayComponentAssessmentIndex();
                    
                    if isempty(assessmentIndex)
                        
                        uialert( ...
                            app.UIFigure, ...
                            ['Select one reference-component assessment ' ...
                            'in the Tree first.'], ...
                            'No component assessment');
                        
                        return
                    end
                    
                    app.CurrentComponentAssessmentIndex = ...
                        assessmentIndex;
                    
                    app.displayComponentAssessmentThreeLevelOnAxis4( ...
                        assessmentIndex);
                    
                    app.displayComponentAssessmentText( ...
                        assessmentIndex);
                    
                otherwise
                    
                    if app.ClusterReady
                        app.displayClusterMap();
                    else
                        app.displayInitialClusterInput();
                    end
            end
            
        end
        
        
        function displayAssignedClassMap(app)
            
            if isempty(app.ClusterLabelMap)
                return
            end
            
            [ny, nx] = ...
                size(app.ClusterLabelMap);
            
            % =============================================================
            % 1. DRAW TRACE BASE
            % =============================================================
            
            app.resetImageAxis(app.UIAxes);
            
            imagesc( ...
                app.UIAxes, ...
                app.PreviewMap);
            
            axis(app.UIAxes, 'image');
            
            app.UIAxes.YDir = ...
                'normal';
            
            colormap(app.UIAxes, gray(256));
            
            traceValues = ...
                app.PreviewMap( ...
                app.ValidMask2D & ...
                isfinite(app.PreviewMap));
            
            if ~isempty(traceValues)
                
                traceLow = ...
                    prctile(traceValues, 2);
                
                traceHigh = ...
                    prctile(traceValues, 98);
                
                if isfinite(traceLow) && ...
                        isfinite(traceHigh) && ...
                        traceLow < traceHigh
                    
                    clim( ...
                        app.UIAxes, ...
                        [traceLow traceHigh]);
                end
            end
            
            hold(app.UIAxes, 'on');
            
            % =============================================================
            % 2. BUILD CLASS OVERLAY
            % =============================================================
            
            classRGB = ...
                zeros(ny, nx, 3);
            
            classAlpha = ...
                zeros(ny, nx);
            
            for clusterIndex = 1:numel(app.Clusters)
                
                C = ...
                    app.Clusters(clusterIndex);
                
                if ~C.Active
                    continue
                end
                
                switch lower(strtrim(C.Class))
                    
                    case lower('Cell candidate')
                        
                        layerColor = ...
                            [0.10 0.85 0.25];
                        
                    case lower('Background candidate')
                        
                        layerColor = ...
                            [0.15 0.45 0.98];
                        
                    case lower('Mixed / uncertain')
                        
                        layerColor = ...
                            [1.00 0.72 0.05];
                        
                    case lower('Artifact candidate')
                        
                        layerColor = ...
                            [0.95 0.10 0.75];
                        
                    case lower('Unassigned')
                        
                        layerColor = ...
                            [0.62 0.62 0.62];
                        
                    otherwise
                        
                        % Custom layer uses the current cluster color.
                        layerColor = ...
                            C.Color;
                end
                
                for channel = 1:3
                    
                    plane = ...
                        classRGB(:, :, channel);
                    
                    plane(C.PixelIdx) = ...
                        layerColor(channel);
                    
                    classRGB(:, :, channel) = ...
                        plane;
                end
                
                classAlpha(C.PixelIdx) = ...
                    app.ClusterOverlayAlpha;
            end
            
            overlayHandle = ...
                image( ...
                app.UIAxes, ...
                classRGB);
            
            overlayHandle.AlphaData = ...
                classAlpha;
            
            overlayHandle.AlphaDataMapping = ...
                'none';
            
            overlayHandle.HitTest = ...
                'off';
            
            % =============================================================
            % 3. CLUSTER BOUNDARIES
            % =============================================================
            
            for clusterIndex = 1:numel(app.Clusters)
                
                C = ...
                    app.Clusters(clusterIndex);
                
                if ~C.Active
                    continue
                end
                
                clusterMask = ...
                    false(ny, nx);
                
                clusterMask(C.PixelIdx) = ...
                    true;
                
                contour( ...
                    app.UIAxes, ...
                    clusterMask, ...
                    [0.5 0.5], ...
                    'Color', ...
                    C.Color, ...
                    'LineWidth', ...
                    0.7, ...
                    'HitTest', ...
                    'off');
            end
            
            hold(app.UIAxes, 'off');
            
            title( ...
                app.UIAxes, ...
                { ...
                app.CurrentScanName, ...
                'Assigned cluster layers over trace', ...
                'Green=cell | blue=background | yellow=mixed | magenta=artifact'}, ...
                'Interpreter', ...
                'none');
            
        end
        
        
        function displayConfidenceMap(app)
            
            if isempty(app.PixelConfidenceMap)
                return
            end
            
            app.resetImageAxis(app.UIAxes);
            
            imagesc( ...
                app.UIAxes, ...
                app.PixelConfidenceMap, ...
                [0 1]);
            
            axis(app.UIAxes, 'image');
            app.UIAxes.YDir = 'normal';
            
            colormap(app.UIAxes, turbo(256));
            colorbar(app.UIAxes);
            
            title( ...
                app.UIAxes, ...
                { ...
                app.CurrentScanName, ...
                'Pixel-to-cluster assignment confidence', ...
                '0=borderline | 1=well separated'}, ...
                'Interpreter', ...
                'none');
            
        end


        function displayClusterPixelCountMap(app)
            
            countMap = ...
                nan(size(app.ClusterLabelMap));
            
            for i = 1:numel(app.Clusters)
                
                C = ...
                    app.Clusters(i);
                
                if ~C.Active
                    continue
                end
                
                countMap(C.PixelIdx) = ...
                    numel(C.PixelIdx);
            end
            
            app.resetImageAxis(app.UIAxes);
            
            imagesc(app.UIAxes, countMap);
            
            axis(app.UIAxes, 'image');
            app.UIAxes.YDir = 'normal';
            
            colormap(app.UIAxes, parula(256));
            colorbar(app.UIAxes);
            
            title( ...
                app.UIAxes, ...
                'Cluster pixel-count map', ...
                'Interpreter', ...
                'none');
            
        end
        
        
        function compareSelectedClusterSpectra(app)
            
            ids = ...
                app.SelectedClusterIDs;
            
            if isempty(ids)
                
                activeIndices = ...
                    find([app.Clusters.Active]);
                
                ids = ...
                    [app.Clusters(activeIndices).ID];
            end
            
            if isempty(ids)
                return
            end
            
            app.resetSpectrumAxis(app.UIAxes_3);
            
            hold(app.UIAxes_3, 'on');
            
            legendText = {};
            yAll = [];
            
            for id = ids(:)'
                
                idx = ...
                    app.findClusterIndexByID(id);
                
                if isempty(idx) || ...
                        ~app.Clusters(idx).Active
                    
                    continue
                end
                
                C = ...
                    app.Clusters(idx);
                
                meanSpec = ...
                    double(C.MeanSpectrum(:));
                
                plot( ...
                    app.UIAxes_3, ...
                    app.Wavenumber, ...
                    meanSpec, ...
                    'Color', ...
                    C.Color, ...
                    'LineWidth', ...
                    1.7);
                
                yAll = ...
                    [yAll; meanSpec]; %#ok<AGROW>
                
                legendText{end + 1} = ...
                    sprintf( ...
                    '%s | %s | n=%d', ...
                    C.Name, ...
                    C.Class, ...
                    numel(C.PixelIdx)); %#ok<AGROW>
            end
            
            hold(app.UIAxes_3, 'off');
            
            title( ...
                app.UIAxes_3, ...
                sprintf( ...
                'Spectral comparison | clusters=%d', ...
                numel(legendText)), ...
                'Interpreter', ...
                'none');
            
            if ~isempty(legendText)
                
                legend( ...
                    app.UIAxes_3, ...
                    legendText, ...
                    'Location', ...
                    'best', ...
                    'Interpreter', ...
                    'none');
            end
            
            app.setNiceSpectralLimits( ...
                app.UIAxes_3, ...
                app.Wavenumber, ...
                yAll);
            
        end


        function saveClusterImages(app)
            
            if isempty(app.RamanCube)
                
                uialert( ...
                    app.UIFigure, ...
                    'No scan is loaded.', ...
                    'Nothing to save');
                
                return
            end
            
            imageOptions = { ...
                'Axis 1 — cluster-level map', ...
                'Axis 3 — selected/mean spectra', ...
                'Axis 2 — pixel-level map', ...
                'Axis 4 — pixel/component result'};
            
            [selectedOptions, accepted] = ...
                listdlg( ...
                'PromptString', ...
                'Select the displayed outputs to save:', ...
                'SelectionMode', ...
                'multiple', ...
                'ListString', ...
                imageOptions, ...
                'InitialValue', ...
                1:numel(imageOptions), ...
                'ListSize', ...
                [390 170]);
            
            if ~accepted || isempty(selectedOptions)
                return
            end
            
            if isempty(app.OutputRoot) || ...
                    ~exist(app.OutputRoot, 'dir')
                
                selectedRoot = ...
                    uigetdir( ...
                    pwd, ...
                    'Select image output folder');
                
                if isequal(selectedRoot, 0)
                    return
                end
                
                baseFolder = ...
                    selectedRoot;
                
            else
                
                baseFolder = ...
                    fullfile( ...
                    app.OutputRoot, ...
                    'single_extract', ...
                    'images', ...
                    app.makeSafeFileName(app.CurrentScanID));
            end
            
            timestampText = ...
                char(datetime( ...
                'now', ...
                'Format', ...
                'yyyyMMdd_HHmmss_SSS'));
            
            outputFolder = ...
                fullfile( ...
                baseFolder, ...
                ['run_' timestampText]);
            
            if ~exist(outputFolder, 'dir')
                
                [created, message] = ...
                    mkdir(outputFolder);
                
                if ~created
                    
                    error( ...
                        ['Could not create image folder:' newline ...
                        outputFolder newline message]);
                end
            end
            
            axesList = { ...
                app.UIAxes, ...
                app.UIAxes_3, ...
                app.UIAxes_2, ...
                app.UIAxes_4};
            
            fileSuffixes = { ...
                'cluster_map', ...
                'spectra', ...
                'pixel_map', ...
                'pixel_component_result'};
            
            savedFiles = {};
            
            scanID = ...
                app.makeSafeFileName(app.CurrentScanID);
            
            for optionNumber = selectedOptions(:)'
                
                outputPath = ...
                    fullfile( ...
                    outputFolder, ...
                    sprintf( ...
                    '%s_%s.png', ...
                    scanID, ...
                    fileSuffixes{optionNumber}));
                
                exportgraphics( ...
                    axesList{optionNumber}, ...
                    outputPath, ...
                    'Resolution', ...
                    300);
                
                savedFiles{end + 1, 1} = ...
                    outputPath; %#ok<AGROW>
            end
            
            app.LastSavedImageFolder = ...
                outputFolder;
            
            reportLines = { ...
                'IMAGES SAVED'; ...
                '================================================'; ...
                ''; ...
                ['Folder: ' outputFolder]; ...
                sprintf( ...
                'Files created: %d', ...
                numel(savedFiles)); ...
                ''};
            
            reportLines = ...
                [reportLines; savedFiles];
            
            app.TextArea.Value = ...
                reportLines;
            
        end


        function startSingleAnalysis(app)
            
            % =============================================================
            % 1. VALIDATE CLUSTER RESULT
            % =============================================================
            
            if ~app.ClusterReady
                
                uialert( ...
                    app.UIFigure, ...
                    'Run cluster segmentation first.', ...
                    'No cluster result');
                
                return
            end
            
            selectedClusterIndices = ...
                app.getSelectedClustersForSingleMode();
            
            if isempty(selectedClusterIndices)
                return
            end
            
            numberSelectedClusters = ...
                numel(selectedClusterIndices);
            
            % =============================================================
            % 2. COUNT USABLE PIXELS
            % =============================================================
            
            usablePixelCounts = ...
                zeros(numberSelectedClusters, 1);
            
            for selectedNumber = 1:numberSelectedClusters
                
                clusterIndex = ...
                    selectedClusterIndices(selectedNumber);
                
                usablePixels = ...
                    app.getUsableRepresentativePixels( ...
                    clusterIndex);
                
                usablePixelCounts(selectedNumber) = ...
                    numel(usablePixels);
            end
            
            if any(usablePixelCounts < 1)
                
                invalidPosition = ...
                    find(usablePixelCounts < 1, 1);
                
                invalidCluster = ...
                    app.Clusters( ...
                    selectedClusterIndices(invalidPosition));
                
                uialert( ...
                    app.UIFigure, ...
                    sprintf( ...
                    '%s contains no usable Raman pixel.', ...
                    invalidCluster.Name), ...
                    'No usable pixels');
                
                return
            end
            
            % =============================================================
            % 3. DETERMINE BALANCED REPRESENTATIVE COUNT
            % =============================================================
            
            try
                
                [representativeCount, decisionInfo] = ...
                    app.determineBalancedRepresentativeCount( ...
                    usablePixelCounts);
                
            catch ME
                
                uialert( ...
                    app.UIFigure, ...
                    ME.message, ...
                    'Representative-count error');
                
                return
            end
            
            if min(usablePixelCounts) < representativeCount
                
                uialert( ...
                    app.UIFigure, ...
                    { ...
                    sprintf( ...
                    'Smallest selected cluster: %d usable pixels', ...
                    min(usablePixelCounts)); ...
                    sprintf( ...
                    'Required representative groups: %d', ...
                    representativeCount); ...
                    ''; ...
                    'Select a larger cluster or revise segmentation.'}, ...
                    'Cluster too small');
                
                return
            end
            
            % =============================================================
            % 4. CONFIRM SINGLE ANALYSIS
            % =============================================================
            
            confirmationLines = ...
                app.buildRepresentativeConfirmationLines( ...
                selectedClusterIndices, ...
                usablePixelCounts, ...
                representativeCount, ...
                decisionInfo);
            
            confirmationLines{1} = ...
                'OPEN SELECTED CLUSTERS IN SINGLE ANALYSIS';
            
            choice = ...
                uiconfirm( ...
                app.UIFigure, ...
                confirmationLines, ...
                'Single analysis', ...
                'Options', ...
                {'Open single analysis', 'Cancel'}, ...
                'DefaultOption', ...
                'Open single analysis', ...
                'CancelOption', ...
                'Cancel');
            
            if ~strcmp(choice, 'Open single analysis')
                return
            end
            
            app.UIFigure.Pointer = ...
                'watch';
            
            pointerCleanup = ...
                onCleanup( ...
                @() set( ...
                app.UIFigure, ...
                'Pointer', ...
                'arrow')); %#ok<NASGU>
            
            app.TextArea.Value = { ...
                'PREPARING SINGLE ANALYSIS'; ...
                '================================================'; ...
                ''; ...
                sprintf( ...
                'Selected clusters: %d', ...
                numberSelectedClusters); ...
                sprintf( ...
                'Representative spectra per cluster: %d', ...
                representativeCount); ...
                ''; ...
                'Building balanced spatial representatives...'};
            
            drawnow;
            
            try
                
                handoffData = ...
                    app.buildBalancedRepresentativeDataset( ...
                    selectedClusterIndices, ...
                    representativeCount);
                
                app.openRepresentativeDatasetInSingleMode( ...
                    handoffData);
                
                app.ClusterConfirmed = ...
                    true;
                
                reportLines = { ...
                    'SINGLE ANALYSIS OPENED'; ...
                    '================================================'; ...
                    ''; ...
                    ['Scan: ' app.CurrentScanName]; ...
                    sprintf( ...
                    'Selected groups: %d', ...
                    handoffData.NumberClusters); ...
                    sprintf( ...
                    'Representatives per group: %d', ...
                    handoffData.RepresentativesPerCluster); ...
                    sprintf( ...
                    'Total representative spectra: %d', ...
                    handoffData.TotalSpectra); ...
                    ''; ...
                    'GROUPS'};
                
                for groupNumber = 1:numel( ...
                        handoffData.ClusterDatasets)
                    
                    D = ...
                        handoffData.ClusterDatasets(groupNumber);
                    
                    reportLines{end + 1, 1} = ...
                        sprintf( ...
                        '%s | ID=%d | usable pixels=%d', ...
                        D.ClusterName, ...
                        D.ClusterID, ...
                        D.UsablePixelCount); %#ok<AGROW>
                end
                
                reportLines = ...
                    [ ...
                    reportLines; ...
                    { ...
                    ''; ...
                    'single_mode opened at the Preprocess tab.'; ...
                    ['Representative spectra are within-scan spatial ' ...
                    'summaries, not biological replicates.']}];
                
                app.TextArea.Value = ...
                    reportLines;
                
            catch ME
                
                if strcmp( ...
                        ME.identifier, ...
                        'single_extract:SingleModeHandoffCancelled')
                    
                    return
                end
                
                uialert( ...
                    app.UIFigure, ...
                    getReport( ...
                    ME, ...
                    'extended', ...
                    'hyperlinks', ...
                    'off'), ...
                    'Single analysis failed');
            end
            
        end
        
        
        function requireClusterResultForDisplay(app)
            
            if ~app.ClusterReady
                
                error( ...
                    'Run cluster segmentation before using this display.');
            end
            
        end
        
        
        function requirePixelAssessmentForDisplay(app)
            
            if ~app.PixelAssessmentReady
                
                error( ...
                    'Run Cell-Background pixel assessment first.');
            end
            
        end
        
        
        function qc = assessRepresentativeSpectralHeterogeneity( ...
                app, ...
                spectra, ...
                groups, ...
                packagePixelIdx)
            
            spectra = ...
                double(spectra);
            
            packagePixelIdx = ...
                double(packagePixelIdx(:));
            
            numberGroups = ...
                numel(groups);
            
            dispersion = ...
                nan(numberGroups, 1);
            
            for groupNumber = 1:numberGroups
                
                spatialPixelIdx = ...
                    double(groups{groupNumber}(:));
                
                [present, spectrumRows] = ...
                    ismember( ...
                    spatialPixelIdx, ...
                    packagePixelIdx);
                
                if ~all(present)
                    
                    error( ...
                        'single_extract:PixelSpectrumAlignment', ...
                        ['One or more spatial pixels cannot be matched to ' ...
                        'ClusterPackage spectra rows.']);
                end
                
                X = ...
                    spectra(spectrumRows, :);
                
                if isempty(X)
                    continue
                end
                
                % =============================================================
                % ROW NORMALIZATION FOR QC ONLY
                %
                % Mean spectra exported later remain original pretreated intensity.
                % =============================================================
                
                Xqc = ...
                    X - ...
                    median(X, 2, 'omitnan');
                
                normValue = ...
                    sqrt( ...
                    sum(Xqc .^ 2, 2));
                
                normValue( ...
                    ~isfinite(normValue) | ...
                    normValue <= eps) = ...
                    1;
                
                Xqc = ...
                    Xqc ./ normValue;
                
                centerSpectrum = ...
                    mean( ...
                    Xqc, ...
                    1, ...
                    'omitnan');
                
                centerNorm = ...
                    norm(centerSpectrum);
                
                if centerNorm > eps
                    
                    centerSpectrum = ...
                        centerSpectrum ./ ...
                        centerNorm;
                end
                
                residual = ...
                    Xqc - ...
                    centerSpectrum;
                
                rowDistance = ...
                    sqrt( ...
                    sum( ...
                    residual .^ 2, ...
                    2));
                
                dispersion(groupNumber) = ...
                    median( ...
                    rowDistance, ...
                    'omitnan');
            end
            
            finiteDispersion = ...
                dispersion(isfinite(dispersion));
            
            if isempty(finiteDispersion)
                
                robustCenter = ...
                    NaN;
                
                robustMAD = ...
                    NaN;
                
                threshold = ...
                    NaN;
                
                flagged = ...
                    false(numberGroups, 1);
                
            else
                
                robustCenter = ...
                    median( ...
                    finiteDispersion, ...
                    'omitnan');
                
                robustMAD = ...
                    1.4826 .* ...
                    median( ...
                    abs( ...
                    finiteDispersion - ...
                    robustCenter), ...
                    'omitnan');
                
                if ~isfinite(robustMAD) || ...
                        robustMAD <= eps
                    
                    threshold = ...
                        Inf;
                    
                else
                    
                    threshold = ...
                        robustCenter + ...
                        app.SingleExtractSpectralQCMADMultiplier .* ...
                        robustMAD;
                end
                
                flagged = ...
                    dispersion > threshold;
            end
            
            qc = struct();
            
            qc.Dispersion = ...
                dispersion;
            
            qc.RobustMedian = ...
                robustCenter;
            
            qc.RobustMAD = ...
                robustMAD;
            
            qc.FlagThreshold = ...
                threshold;
            
            qc.Flagged = ...
                flagged;
            
            qc.FlaggedCount = ...
                nnz(flagged);
            
        end
        
        
        function displaySelectedSpectralGroups(app, groups)
            
            % =============================================================
            % DISPLAY NORMAL CLUSTERS AND PURITY COMPONENTS TOGETHER
            %
            % Every selected Tree node is represented by one mean spectrum.
            % When exactly one group is selected, mean +/- standard deviation
            % is displayed.
            % =============================================================
            
            if nargin < 2 || ...
                    isempty(groups)
                
                app.displayAllClusterMeans();
                return
            end
            
            if isempty(app.RamanCube) || ...
                    isempty(app.Wavenumber)
                
                return
            end
            
            [numberRows, numberColumns, numberWavenumbers] = ...
                size(app.RamanCube);
            
            allSpectra = ...
                reshape( ...
                double(app.RamanCube), ...
                numberRows * numberColumns, ...
                numberWavenumbers);
            
            app.resetSpectrumAxis(app.UIAxes_3);
            
            hold(app.UIAxes_3, 'on');
            
            yAll = [];
            legendText = {};
            
            displayedCount = ...
                0;
            
            for groupNumber = 1:numel(groups)
                
                pixelIdx = ...
                    unique( ...
                    double(groups(groupNumber).PixelIdx(:)), ...
                    'stable');
                
                validIndex = ...
                    pixelIdx >= 1 & ...
                    pixelIdx <= numberRows * numberColumns;
                
                pixelIdx = ...
                    pixelIdx(validIndex);
                
                if isempty(pixelIdx)
                    continue
                end
                
                spectra = ...
                    allSpectra(pixelIdx, :);
                
                finiteRows = ...
                    all(isfinite(spectra), 2);
                
                spectra = ...
                    spectra(finiteRows, :);
                
                if isempty(spectra)
                    continue
                end
                
                meanSpectrum = ...
                    mean( ...
                    spectra, ...
                    1, ...
                    'omitnan');
                
                standardDeviation = ...
                    std( ...
                    spectra, ...
                    0, ...
                    1, ...
                    'omitnan');
                
                colorValue = ...
                    groups(groupNumber).Color;
                
                displayedCount = ...
                    displayedCount + 1;
                
                % =========================================================
                % SHOW SD BAND ONLY FOR ONE SELECTED GROUP
                % =========================================================
                
                if numel(groups) == 1
                    
                    upperSpectrum = ...
                        meanSpectrum + standardDeviation;
                    
                    lowerSpectrum = ...
                        meanSpectrum - standardDeviation;
                    
                    fill( ...
                        app.UIAxes_3, ...
                        [ ...
                        app.Wavenumber(:); ...
                        flipud(app.Wavenumber(:))], ...
                        [ ...
                        upperSpectrum(:); ...
                        flipud(lowerSpectrum(:))], ...
                        colorValue, ...
                        'FaceAlpha', ...
                        0.16, ...
                        'EdgeColor', ...
                        'none', ...
                        'HitTest', ...
                        'off');
                    
                    yAll = ...
                        [ ...
                        yAll; ...
                        upperSpectrum(:); ...
                        lowerSpectrum(:)]; %#ok<AGROW>
                    
                else
                    
                    yAll = ...
                        [ ...
                        yAll; ...
                        meanSpectrum(:)]; %#ok<AGROW>
                end
                
                plot( ...
                    app.UIAxes_3, ...
                    app.Wavenumber, ...
                    meanSpectrum, ...
                    'Color', ...
                    colorValue, ...
                    'LineWidth', ...
                    1.7);
                
                if isempty(groups(groupNumber).PurityClass)
                    
                    legendEntry = ...
                        sprintf( ...
                        '%s | n=%d', ...
                        groups(groupNumber).Name, ...
                        size(spectra, 1));
                    
                else
                    
                    legendEntry = ...
                        sprintf( ...
                        '%s | %s | n=%d', ...
                        groups(groupNumber).Name, ...
                        groups(groupNumber).PurityClass, ...
                        size(spectra, 1));
                end
                
                legendText{end + 1} = ...
                    legendEntry; %#ok<AGROW>
            end
            
            hold(app.UIAxes_3, 'off');
            
            if displayedCount < 1
                
                title( ...
                    app.UIAxes_3, ...
                    'No usable spectra in selected Tree nodes', ...
                    'Interpreter', ...
                    'none');
                
                return
            end
            
            title( ...
                app.UIAxes_3, ...
                sprintf( ...
                'Selected cluster / purity spectra | groups=%d', ...
                displayedCount), ...
                'Interpreter', ...
                'none');
            
            legend( ...
                app.UIAxes_3, ...
                legendText, ...
                'Location', ...
                'best', ...
                'Interpreter', ...
                'none');
            
            app.setNiceSpectralLimits( ...
                app.UIAxes_3, ...
                app.Wavenumber, ...
                yAll);
            
        end


        function colors = makeSplitColors(app, parentColor, numberOfChildren) %#ok<MANU>
            
            parentColor = ...
                max( ...
                0, ...
                min( ...
                1, ...
                double(parentColor(:).')));
            
            numberOfChildren = ...
                max(2, round(numberOfChildren));
            
            % Generate distinct hue offsets.
            hsvParent = ...
                rgb2hsv(parentColor);
            
            hueOffsets = ...
                linspace( ...
                -0.16, ...
                0.16, ...
                numberOfChildren);
            
            valueOffsets = ...
                linspace( ...
                0.12, ...
                -0.10, ...
                numberOfChildren);
            
            saturationOffsets = ...
                linspace( ...
                -0.08, ...
                0.08, ...
                numberOfChildren);
            
            colors = ...
                zeros(numberOfChildren, 3);
            
            for childNumber = 1:numberOfChildren
                
                childHSV = ...
                    hsvParent;
                
                childHSV(1) = ...
                    mod( ...
                    childHSV(1) + ...
                    hueOffsets(childNumber), ...
                    1);
                
                childHSV(2) = ...
                    max( ...
                    0.35, ...
                    min( ...
                    1, ...
                    childHSV(2) + ...
                    saturationOffsets(childNumber)));
                
                childHSV(3) = ...
                    max( ...
                    0.35, ...
                    min( ...
                    1, ...
                    childHSV(3) + ...
                    valueOffsets(childNumber)));
                
                colors(childNumber, :) = ...
                    hsv2rgb(childHSV);
            end
            
        end


        function cleanPixelReferenceROIs(app)
            
            % =============================================================
            % 1. VALIDATE STATE
            % =============================================================
            
            if isempty(app.ReferenceROIs)
                
                error( ...
                    'No reference ROI has been selected.');
            end
            
            if isempty(app.MetricPixelIdx) || ...
                    isempty(app.AllPCAScores)
                
                error( ...
                    ['Pixel assessment requires PCA clustering results. ' ...
                    'Run Cluster segmentation first.']);
            end
            
            [ny, nx] = ...
                size(app.ValidMask2D);
            
            backgroundMask = ...
                false(ny, nx);
            
            cellMask = ...
                false(ny, nx);
            
            erosionRadius = ...
                max( ...
                0, ...
                round(app.ReferenceErosionRadius));
            
            if erosionRadius > 0
                
                erosionElement = ...
                    strel( ...
                    'disk', ...
                    erosionRadius, ...
                    0);
                
            else
                
                erosionElement = [];
            end
            
            % =============================================================
            % 2. CLEAN EVERY ROI
            % =============================================================
            
            for roiIndex = 1:numel(app.ReferenceROIs)
                
                originalRawMask = ...
                    logical( ...
                    app.ReferenceROIs(roiIndex).RawMask);
                
                % Keep complete user-selected RawMask in the ROI state.
                usableRawMask = ...
                    originalRawMask & ...
                    app.ValidMask2D & ...
                    ~app.ArtifactMask2D;
                
                originalSelectedCount = ...
                    nnz(originalRawMask);
                
                validBeforeErosionCount = ...
                    nnz(usableRawMask);
                
                % =========================================================
                % ADAPTIVE EROSION
                %
                % Do not destroy very small ROIs by eroding them.
                % =========================================================
                
                if erosionRadius > 0 && ...
                        validBeforeErosionCount >= 20
                    
                    erodedMask = ...
                        imerode( ...
                        usableRawMask, ...
                        erosionElement);
                    
                    % If erosion removes nearly everything, fall back to
                    % the non-eroded usable ROI.
                    if nnz(erodedMask) < ...
                            max(3, round(0.20 * validBeforeErosionCount))
                        
                        safeMask = ...
                            usableRawMask;
                        
                        erosionApplied = ...
                            false;
                        
                    else
                        
                        safeMask = ...
                            erodedMask;
                        
                        erosionApplied = ...
                            true;
                    end
                    
                else
                    
                    safeMask = ...
                        usableRawMask;
                    
                    erosionApplied = ...
                        false;
                end
                
                safeMask = ...
                    safeMask & ...
                    app.ValidMask2D & ...
                    ~app.ArtifactMask2D;
                
                erodedCount = ...
                    nnz(safeMask);
                
                % =========================================================
                % MATCH ROI PIXELS TO PCA ROWS
                % =========================================================
                
                safePixelIdx = ...
                    find(safeMask);
                
                [knownPixel, metricRows] = ...
                    ismember( ...
                    safePixelIdx, ...
                    app.MetricPixelIdx);
                
                safePixelIdx = ...
                    safePixelIdx(knownPixel);
                
                metricRows = ...
                    metricRows(knownPixel);
                
                cleanMask = ...
                    false(ny, nx);
                
                removedOutlierCount = ...
                    0;
                
                % =========================================================
                % SPECTRAL OUTLIER REMOVAL
                % =========================================================
                
                if isempty(metricRows)
                    
                    usablePixelIdx = [];
                    
                elseif numel(metricRows) < 5
                    
                    % Too few spectra for stable MAD-based outlier QC.
                    % Keep them and mark the ROI as small.
                    usablePixelIdx = ...
                        safePixelIdx;
                    
                else
                    
                    roiScores = ...
                        app.AllPCAScores(metricRows, :);
                    
                    scoreMedian = ...
                        median( ...
                        roiScores, ...
                        1, ...
                        'omitnan');
                    
                    robustDistances = ...
                        sqrt( ...
                        sum( ...
                        (roiScores - scoreMedian) .^ 2, ...
                        2));
                    
                    distanceMedian = ...
                        median( ...
                        robustDistances, ...
                        'omitnan');
                    
                    distanceMAD = ...
                        mad( ...
                        robustDistances, ...
                        1);
                    
                    if ~isfinite(distanceMAD) || ...
                            distanceMAD <= eps
                        
                        keepPixel = ...
                            true(size(robustDistances));
                        
                    else
                        
                        outlierThreshold = ...
                            distanceMedian + ...
                            app.ReferenceOutlierMADThreshold * ...
                            distanceMAD;
                        
                        keepPixel = ...
                            robustDistances <= ...
                            outlierThreshold;
                    end
                    
                    usablePixelIdx = ...
                        safePixelIdx(keepPixel);
                    
                    removedOutlierCount = ...
                        nnz(~keepPixel);
                end
                
                cleanMask(usablePixelIdx) = ...
                    true;
                
                usableCount = ...
                    nnz(cleanMask);
                
                if erodedCount > 0
                    
                    outlierFraction = ...
                        removedOutlierCount / erodedCount;
                    
                else
                    
                    outlierFraction = ...
                        NaN;
                end
                
                % =========================================================
                % ROI STATUS
                % =========================================================
                
                if validBeforeErosionCount == 0
                    
                    roiStatus = ...
                        'Rejected - outside valid/artifact-free region';
                    
                elseif usableCount < app.MinUsablePixelsPerROI
                    
                    roiStatus = ...
                        sprintf( ...
                        'Rejected - only %d clean pixels', ...
                        usableCount);
                    
                elseif usableCount < 30
                    
                    roiStatus = ...
                        'Accepted - small ROI';
                    
                elseif isfinite(outlierFraction) && ...
                        outlierFraction > 0.30
                    
                    roiStatus = ...
                        'Accepted - high outlier fraction';
                    
                else
                    
                    roiStatus = ...
                        'Accepted';
                end
                
                % =========================================================
                % STORE QC
                % =========================================================
                
                % Preserve full drawn ROI.
                app.ReferenceROIs(roiIndex).RawMask = ...
                    originalRawMask;
                
                app.ReferenceROIs(roiIndex).CleanMask = ...
                    cleanMask;
                
                app.ReferenceROIs(roiIndex).OriginalPixelCount = ...
                    originalSelectedCount;
                
                app.ReferenceROIs(roiIndex).ErodedPixelCount = ...
                    erodedCount;
                
                app.ReferenceROIs(roiIndex).UsablePixelCount = ...
                    usableCount;
                
                app.ReferenceROIs(roiIndex).OutlierFraction = ...
                    outlierFraction;
                
                app.ReferenceROIs(roiIndex).Status = ...
                    roiStatus;
                
                % =========================================================
                % ADD ACCEPTED ROI TO POOLED CLASS MASK
                % =========================================================
                
                if usableCount >= app.MinUsablePixelsPerROI
                    
                    if strcmp( ...
                            app.ReferenceROIs(roiIndex).Class, ...
                            'Background')
                        
                        backgroundMask = ...
                            backgroundMask | ...
                            cleanMask;
                        
                    elseif strcmp( ...
                            app.ReferenceROIs(roiIndex).Class, ...
                            'Cell')
                        
                        cellMask = ...
                            cellMask | ...
                            cleanMask;
                    end
                end
                
                %#ok<NASGU>
                erosionApplied = erosionApplied;
                
            end
            
            % =============================================================
            % 3. REMOVE CROSS-CLASS OVERLAP
            % =============================================================
            
            overlapMask = ...
                backgroundMask & ...
                cellMask;
            
            overlapCount = ...
                nnz(overlapMask);
            
            if overlapCount > 0
                
                backgroundMask(overlapMask) = ...
                    false;
                
                cellMask(overlapMask) = ...
                    false;
            end
            
            app.BackgroundReferenceMask2D = ...
                backgroundMask;
            
            app.CellReferenceMask2D = ...
                cellMask;
            
            totalBackgroundClean = ...
                nnz(backgroundMask);
            
            totalCellClean = ...
                nnz(cellMask);
            
            % =============================================================
            % 4. SHOW ROI QC BEFORE STOPPING
            % =============================================================
            
            qcLines = { ...
                'REFERENCE ROI CLEANING'; ...
                '================================'; ...
                ''; ...
                sprintf( ...
                'Background clean pixels: %d', ...
                totalBackgroundClean); ...
                sprintf( ...
                'Cell clean pixels: %d', ...
                totalCellClean); ...
                sprintf( ...
                'Cross-class overlap removed: %d', ...
                overlapCount); ...
                ''; ...
                'ROI DETAILS'};
            
            for roiIndex = 1:numel(app.ReferenceROIs)
                
                R = ...
                    app.ReferenceROIs(roiIndex);
                
                qcLines{end + 1} = ...
                    sprintf( ...
                    ['ROI %d | %s | drawn=%d | ' ...
                    'after erosion=%d | clean=%d | %s'], ...
                    R.ID, ...
                    R.Class, ...
                    R.OriginalPixelCount, ...
                    R.ErodedPixelCount, ...
                    R.UsablePixelCount, ...
                    R.Status); %#ok<AGROW>
            end
            
            app.TextArea.Value = ...
                qcLines;
            
            drawnow;
            
            % =============================================================
            % 5. REQUIRE ENOUGH POOLED PIXELS PER CLASS
            % =============================================================
            
            missingMessages = {};
            
            if totalBackgroundClean < ...
                    app.MinTotalUsablePixelsPerClass
                
                missingMessages{end + 1} = ...
                    sprintf( ...
                    ['Background has only %d clean pixels; ' ...
                    'at least %d pooled pixels are required.'], ...
                    totalBackgroundClean, ...
                    app.MinTotalUsablePixelsPerClass); %#ok<AGROW>
            end
            
            if totalCellClean < ...
                    app.MinTotalUsablePixelsPerClass
                
                missingMessages{end + 1} = ...
                    sprintf( ...
                    ['Cell has only %d clean pixels; ' ...
                    'at least %d pooled pixels are required.'], ...
                    totalCellClean, ...
                    app.MinTotalUsablePixelsPerClass); %#ok<AGROW>
            end
            
            if ~isempty(missingMessages)
                
                error( ...
                    strjoin( ...
                    [ ...
                    missingMessages, ...
                    { ...
                    'Add larger or additional reference ROIs and rerun pixel assessment.'}], ...
                    newline));
            end
            
        end
        
        
        function groupResult = generateOneSingleExtractGroup( ...
                app, ...
                groupDefinition, ...
                targetPixels)
            
            sampleIndices = ...
                double( ...
                groupDefinition.SampleIndices(:).');
            
            if isempty(sampleIndices)
                
                error( ...
                    'single_extract:EmptyOutputGroup', ...
                    sprintf( ...
                    'Group "%s" contains no sample.', ...
                    groupDefinition.Name));
            end
            
            commonWN = ...
                app.validateSingleExtractWavenumberAlignment( ...
                sampleIndices);
            
            allMeanSpectra = ...
                [];
            
            sampleResults = ...
                struct([]);
            
            sourceSampleNames = ...
                strings(0, 1);
            
            sourceRepresentativeNumbers = ...
                zeros(0, 1);
            
            % =============================================================
            % EACH SCAN IS PARTITIONED INDEPENDENTLY
            % =============================================================
            
            for samplePosition = 1:numel(sampleIndices)
                
                sampleIndex = ...
                    sampleIndices(samplePosition);
                
                oneResult = ...
                    app.generateRepresentativesForOneSample( ...
                    sampleIndex, ...
                    targetPixels);
                
                if isempty(sampleResults)
                    
                    sampleResults = ...
                        oneResult;
                    
                else
                    
                    sampleResults(end + 1) = ...
                        oneResult; %#ok<AGROW>
                end
                
                numberCurrent = ...
                    oneResult.NumberRepresentatives;
                
                allMeanSpectra = ...
                    [ ...
                    allMeanSpectra; ...
                    oneResult.MeanSpectra]; %#ok<AGROW>
                
                sourceSampleNames = ...
                    [ ...
                    sourceSampleNames; ...
                    repmat( ...
                    string(oneResult.SampleName), ...
                    numberCurrent, ...
                    1)]; %#ok<AGROW>
                
                sourceRepresentativeNumbers = ...
                    [ ...
                    sourceRepresentativeNumbers; ...
                    (1:numberCurrent)']; %#ok<AGROW>
            end
            
            % =============================================================
            % FINAL GROUP RESULT
            % =============================================================
            
            groupResult = struct();
            
            groupResult.Name = ...
                groupDefinition.Name;
            
            groupResult.SampleIndices = ...
                sampleIndices;
            
            groupResult.Wavenumber = ...
                commonWN;
            
            groupResult.MeanSpectra = ...
                allMeanSpectra;
            
            groupResult.SampleResults = ...
                sampleResults;
            
            groupResult.SourceSampleName = ...
                sourceSampleNames;
            
            groupResult.SourceRepresentativeNumber = ...
                sourceRepresentativeNumbers;
            
            groupResult.NumberRepresentatives = ...
                size(allMeanSpectra, 1);
            
            groupResult.TargetPixelsPerMean = ...
                targetPixels;
            
        end
        
        
        function textValue = singleExtractPassFlagText(app, flagged) %#ok<INUSD>
            
            if flagged
                
                textValue = ...
                    'FLAGGED';
                
            else
                
                textValue = ...
                    'PASS';
            end
            
        end


        function selectedPixelIdx = sampleSpatiallyDistributedPixels( ...
                app, ...
                roiMask, ...
                requestedCount) %#ok<INUSD>
            
            pixelIdx = find(roiMask);
            
            numberAvailable = numel(pixelIdx);
            
            requestedCount = ...
                min( ...
                numberAvailable, ...
                max(1, round(requestedCount)));
            
            if numberAvailable <= requestedCount
                
                selectedPixelIdx = ...
                    pixelIdx(:);
                
                return
            end
            
            [rows, columns] = ...
                ind2sub( ...
                size(roiMask), ...
                pixelIdx);
            
            spatialTable = ...
                [rows(:), columns(:), pixelIdx(:)];
            
            spatialTable = ...
                sortrows( ...
                spatialTable, ...
                [1 2]);
            
            selectedPositions = ...
                unique( ...
                round( ...
                linspace( ...
                1, ...
                numberAvailable, ...
                requestedCount)));
            
            selectedPixelIdx = ...
                spatialTable( ...
                selectedPositions, ...
                3);
            
            selectedPixelIdx = ...
                selectedPixelIdx(:);
            
        end
        
        
        function [medianLargestFraction, ...
                medianIsolatedFraction, ...
                totalComponentCount] = ...
                calculateCandidateSpatialQC( ...
                app, ...
                labelsAll, ...
                pixelIdx, ...
                numberClusters)
            
            % =============================================================
            % SPATIAL QC FOR ONE CANDIDATE MEDOID MAP
            %
            % This function does not modify app.ClusterLabelMap.
            % It creates one temporary label map only for QC.
            % =============================================================
            
            labelsAll = ...
                double(labelsAll(:));
            
            pixelIdx = ...
                double(pixelIdx(:));
            
            numberClusters = ...
                round(double(numberClusters));
            
            if numel(labelsAll) ~= numel(pixelIdx)
                
                error( ...
                    ['Spatial-QC labels and pixel indices must contain ' ...
                    'the same number of entries.']);
            end
            
            temporaryLabelMap = ...
                zeros(size(app.ValidMask2D));
            
            temporaryLabelMap(pixelIdx) = ...
                labelsAll;
            
            largestFractions = ...
                nan(numberClusters, 1);
            
            isolatedFractions = ...
                nan(numberClusters, 1);
            
            componentCounts = ...
                zeros(numberClusters, 1);
            
            for clusterNumber = 1:numberClusters
                
                currentMask = ...
                    temporaryLabelMap == ...
                    clusterNumber;
                
                clusterPixelCount = ...
                    nnz(currentMask);
                
                if clusterPixelCount < 1
                    continue
                end
                
                connected = ...
                    bwconncomp( ...
                    currentMask, ...
                    8);
                
                componentCounts(clusterNumber) = ...
                    connected.NumObjects;
                
                if connected.NumObjects < 1
                    
                    largestFractions(clusterNumber) = ...
                        0;
                    
                    isolatedFractions(clusterNumber) = ...
                        0;
                    
                    continue
                end
                
                componentSizes = ...
                    cellfun( ...
                    @numel, ...
                    connected.PixelIdxList);
                
                largestFractions(clusterNumber) = ...
                    max(componentSizes) / ...
                    clusterPixelCount;
                
                isolatedFractions(clusterNumber) = ...
                    nnz(componentSizes <= 2) / ...
                    connected.NumObjects;
            end
            
            validLargest = ...
                isfinite(largestFractions);
            
            validIsolated = ...
                isfinite(isolatedFractions);
            
            if any(validLargest)
                
                medianLargestFraction = ...
                    median( ...
                    largestFractions(validLargest), ...
                    'omitnan');
                
            else
                
                medianLargestFraction = ...
                    NaN;
            end
            
            if any(validIsolated)
                
                medianIsolatedFraction = ...
                    median( ...
                    isolatedFractions(validIsolated), ...
                    'omitnan');
                
            else
                
                medianIsolatedFraction = ...
                    NaN;
            end
            
            totalComponentCount = ...
                sum(componentCounts);
            
        end
        
        
        function runTable = ...
                buildSelectedRunEvidenceTable(app)
            
            % =============================================================
            % ONE ROW PER FINAL K-MEANS RUN AT THE SELECTED PC DIMENSION
            % =============================================================
            
            numberRuns = ...
                numel(app.ClusterRunResults);
            
            RunNumber = ...
                nan(numberRuns, 1);
            
            Seed = ...
                nan(numberRuns, 1);
            
            TotalSSE = ...
                nan(numberRuns, 1);
            
            MedianARI = ...
                nan(numberRuns, 1);
            
            IsSelectedMedoid = ...
                false(numberRuns, 1);
            
            for runIndex = 1:numberRuns
                
                R = ...
                    app.ClusterRunResults(runIndex);
                
                RunNumber(runIndex) = ...
                    R.RunNumber;
                
                Seed(runIndex) = ...
                    R.Seed;
                
                TotalSSE(runIndex) = ...
                    R.TotalSSE;
                
                MedianARI(runIndex) = ...
                    R.MedianARI;
                
                IsSelectedMedoid(runIndex) = ...
                    runIndex == app.ClusterSelectedRun;
            end
            
            runTable = ...
                table( ...
                RunNumber, ...
                Seed, ...
                TotalSSE, ...
                MedianARI, ...
                IsSelectedMedoid);
            
        end
        
        
        function writeClusterEvidenceSummary( ...
                app, ...
                summaryPath, ...
                params, ...
                selectionSummary, ...
                evidenceFolder)
            
            selectedIndex = ...
                app.PCASelectionSelectedIndex;
            
            selectedResult = ...
                app.PCASelectionResults(selectedIndex);
            
            fileID = ...
                fopen( ...
                summaryPath, ...
                'w');
            
            if fileID < 0
                
                error( ...
                    'single_extract:EvidenceSummaryOpenFailed', ...
                    ['Could not open summary file:' newline summaryPath]);
            end
            
            cleanupObject = ...
                onCleanup( ...
                @() fclose(fileID)); %#ok<NASGU>
            
            fprintf(fileID, ...
                'CLUSTER SEGMENTATION EVIDENCE SUMMARY\n');
            
            fprintf(fileID, ...
                '============================================================\n\n');
            
            fprintf(fileID, ...
                'Evidence format: %s\n', ...
                app.ClusterEvidenceVersion);
            
            fprintf(fileID, ...
                'Created: %s\n', ...
                char(datetime('now', ...
                'Format', 'yyyy-MM-dd HH:mm:ss.SSS')));
            
            fprintf(fileID, ...
                'Scan name: %s\n', ...
                app.CurrentScanName);
            
            fprintf(fileID, ...
                'Scan ID: %s\n', ...
                app.CurrentScanID);
            
            fprintf(fileID, ...
                'Source MAT path: %s\n', ...
                app.SourceMatPath);
            
            fprintf(fileID, ...
                'Evidence folder: %s\n\n', ...
                evidenceFolder);
            
            fprintf(fileID, ...
                'INPUT\n');
            
            fprintf(fileID, ...
                '------------------------------------------------------------\n');
            
            fprintf(fileID, ...
                'Valid spectra: %d\n', ...
                numel(app.MetricPixelIdx));
            
            fprintf(fileID, ...
                'Training spectra: %d\n', ...
                numel(app.TrainingLocalIdx));
            
            fprintf(fileID, ...
                'K: %d\n', ...
                params.K);
            
            fprintf(fileID, ...
                'Automatic PC selection: %s\n', ...
                app.yesNo(params.AutomaticPCSelection));
            
            fprintf(fileID, ...
                'PC candidates: %s\n', ...
                mat2str(params.PCCandidates));
            
            fprintf(fileID, ...
                'Maximum PCA model PCs: %d\n', ...
                params.MaxPC);
            
            fprintf(fileID, ...
                'Coarse runs per candidate: %d\n', ...
                params.CoarseRuns);
            
            fprintf(fileID, ...
                'Final candidate count: %d\n', ...
                params.FinalCandidateCount);
            
            fprintf(fileID, ...
                'Final runs per candidate: %d\n', ...
                params.StabilityRuns);
            
            fprintf(fileID, ...
                'Internal K-means replicates: %d\n', ...
                params.Replicates);
            
            fprintf(fileID, ...
                'K-means maximum iterations: %d\n', ...
                params.MaxIter);
            
            fprintf(fileID, ...
                'Base random seed: %d\n\n', ...
                params.RandomSeed);
            
            fprintf(fileID, ...
                'SELECTED PCA DIMENSION\n');
            
            fprintf(fileID, ...
                '------------------------------------------------------------\n');
            
            fprintf(fileID, ...
                'Selected PCs: %d\n', ...
                selectedResult.PCCount);
            
            fprintf(fileID, ...
                'Explained variance: %.6f%%\n', ...
                selectedResult.ExplainedVariance);
            
            fprintf(fileID, ...
                'Selection rule: %s\n', ...
                selectionSummary.SelectionRule);
            
            fprintf(fileID, ...
                'Fallback used: %s\n', ...
                app.yesNo(selectionSummary.UsedFallback));
            
            fprintf(fileID, ...
                'Selection reason: %s\n\n', ...
                selectedResult.SelectionReason);
            
            fprintf(fileID, ...
                'SELECTED K-MEANS SOLUTION\n');
            
            fprintf(fileID, ...
                '------------------------------------------------------------\n');
            
            fprintf(fileID, ...
                'Independent runs: %d\n', ...
                selectedResult.NumberRuns);
            
            fprintf(fileID, ...
                'Overall median pairwise ARI: %.9f\n', ...
                selectedResult.OverallMedianARI);
            
            fprintf(fileID, ...
                'Medoid run: %d\n', ...
                selectedResult.SelectedRun);
            
            fprintf(fileID, ...
                'Medoid seed: %d\n', ...
                selectedResult.SelectedSeed);
            
            fprintf(fileID, ...
                'Medoid median ARI: %.9f\n', ...
                selectedResult.MedoidMedianARI);
            
            fprintf(fileID, ...
                'Stability level: %s\n', ...
                selectedResult.StabilityLevel);
            
            fprintf(fileID, ...
                'Median assignment confidence: %.9f\n', ...
                selectedResult.MedianConfidence);
            
            fprintf(fileID, ...
                'Low-confidence fraction: %.9f\n', ...
                selectedResult.LowConfidenceFraction);
            
            fprintf(fileID, ...
                'Median largest-component fraction: %.9f\n', ...
                selectedResult.MedianLargestComponentFraction);
            
            fprintf(fileID, ...
                'Median isolated-component fraction: %.9f\n', ...
                selectedResult.MedianIsolatedComponentFraction);
            
        end
        
        function candidateResult = evaluateOnePCCandidate( ...
                app, ...
                allScoresMaximum, ...
                trainingLocalIdx, ...
                pixelIdx, ...
                pcCount, ...
                numberRuns, ...
                params, ...
                stageName)
            
            % =============================================================
            % EVALUATE ONE PCA DIMENSION
            %
            % Inputs:
            %   allScoresMaximum:
            %       PCA scores of all valid pixels up to the maximum PCA
            %       dimension fitted once by runClusterSegmentation.
            %
            %   trainingLocalIdx:
            %       fixed training rows. These rows must remain unchanged
            %       across every PC candidate.
            %
            %   pixelIdx:
            %       linear image indices corresponding to score rows.
            %
            %   pcCount:
            %       number of leading PCA score columns evaluated.
            %
            %   numberRuns:
            %       independent K-means seeds for this candidate.
            %
            %   stageName:
            %       'Coarse' or 'Final'.
            %
            % Output:
            %   candidateResult containing stability, confidence, spatial
            %   fragmentation, medoid labels, centroids, and all run data.
            % =============================================================
            
            % =============================================================
            % 1. VALIDATE INPUT
            % =============================================================
            
            pcCount = ...
                round(double(pcCount));
            
            numberRuns = ...
                round(double(numberRuns));
            
            if pcCount < 1 || ...
                    pcCount > size(allScoresMaximum, 2)
                
                error( ...
                    ['Requested PC count is outside the available ' ...
                    'PCA score dimensions.']);
            end
            
            if numberRuns < 2
                
                error( ...
                    'At least two runs are required for PC stability assessment.');
            end
            
            if isempty(trainingLocalIdx)
                
                error( ...
                    'TrainingLocalIdx is empty during PC candidate evaluation.');
            end
            
            if isempty(pixelIdx)
                
                error( ...
                    'PixelIdx is empty during PC candidate evaluation.');
            end
            
            stageName = ...
                char(string(stageName));
            
            % =============================================================
            % 2. CREATE NESTED PCA SCORE SPACE
            %
            % PCA is not recalculated here. We only take the leading
            % PC1:pcCount columns from the common PCA model.
            % =============================================================
            
            candidateScoresAll = ...
                double( ...
                allScoresMaximum(:, 1:pcCount));
            
            candidateScoresTraining = ...
                candidateScoresAll(trainingLocalIdx, :);
            
            numberPixels = ...
                size(candidateScoresAll, 1);
            
            if numberPixels ~= numel(pixelIdx)
                
                error( ...
                    ['The number of PCA score rows does not match ' ...
                    'the number of valid pixel indices.']);
            end
            
            % =============================================================
            % 3. PREALLOCATE RUN RESULTS
            % =============================================================
            
            runResults = ...
                repmat( ...
                struct( ...
                'RunNumber', [], ...
                'Seed', [], ...
                'LabelsAll', [], ...
                'LabelsTraining', [], ...
                'Centroids', [], ...
                'SumDistance', [], ...
                'TotalSSE', [], ...
                'MedianARI', NaN), ...
                numberRuns, ...
                1);
            
            % Use a deterministic seed region for each PC dimension.
            %
            % Example:
            %   PC 15 coarse run 1 -> base + 15000 + 1
            %
            % This prevents two PC candidates from sharing exactly the same
            % random stream while preserving complete reproducibility.
            candidateSeedBase = ...
                params.RandomSeed + ...
                1000 * pcCount;
            
            % =============================================================
            % 4. MULTI-SEED K-MEANS
            % =============================================================
            
            for runNumber = 1:numberRuns
                
                currentSeed = ...
                    candidateSeedBase + ...
                    runNumber - 1;
                
                rng(currentSeed, 'twister');
                
                [labelsTraining, centroids, sumDistance] = ...
                    kmeans( ...
                    candidateScoresTraining, ...
                    params.K, ...
                    'Distance', ...
                    'sqeuclidean', ...
                    'Start', ...
                    'plus', ...
                    'Replicates', ...
                    params.Replicates, ...
                    'MaxIter', ...
                    params.MaxIter, ...
                    'EmptyAction', ...
                    'singleton', ...
                    'Display', ...
                    'off');
                
                distanceMatrixAll = ...
                    pdist2( ...
                    candidateScoresAll, ...
                    centroids, ...
                    'squaredeuclidean');
                
                [~, labelsAll] = ...
                    min( ...
                    distanceMatrixAll, ...
                    [], ...
                    2);
                
                runResults(runNumber).RunNumber = ...
                    runNumber;
                
                runResults(runNumber).Seed = ...
                    currentSeed;
                
                runResults(runNumber).LabelsAll = ...
                    uint16(labelsAll);
                
                runResults(runNumber).LabelsTraining = ...
                    uint16(labelsTraining);
                
                runResults(runNumber).Centroids = ...
                    double(centroids);
                
                runResults(runNumber).SumDistance = ...
                    double(sumDistance(:));
                
                runResults(runNumber).TotalSSE = ...
                    sum( ...
                    double(sumDistance), ...
                    'omitnan');
                
                if runNumber == 1 || ...
                        runNumber == numberRuns || ...
                        mod(runNumber, 4) == 0
                    
                    app.TextArea.Value = { ...
                        'AUTOMATIC PCA-DIMENSION SEARCH'; ...
                        '================================================'; ...
                        ''; ...
                        sprintf( ...
                        'Stage: %s', ...
                        stageName); ...
                        sprintf( ...
                        'PC candidate: %d', ...
                        pcCount); ...
                        sprintf( ...
                        'K-means run: %d / %d', ...
                        runNumber, ...
                        numberRuns); ...
                        sprintf( ...
                        'Current seed: %d', ...
                        currentSeed); ...
                        ''; ...
                        'The PCA model and training subset remain fixed.'};
                    
                    drawnow limitrate;
                end
            end
            
            % =============================================================
            % 5. PAIRWISE ARI AND MEDOID RUN
            % =============================================================
            
            stability = ...
                app.evaluateKMeansStability( ...
                runResults);
            
            runResults = ...
                stability.RunResults;
            
            selectedRun = ...
                stability.SelectedRun;
            
            selectedResult = ...
                runResults(selectedRun);
            
            medoidLabels = ...
                double(selectedResult.LabelsAll);
            
            medoidCentroids = ...
                double(selectedResult.Centroids);
            
            % =============================================================
            % 6. FINAL DISTANCES FOR MEDOID SOLUTION
            % =============================================================
            
            finalDistanceMatrix = ...
                pdist2( ...
                candidateScoresAll, ...
                medoidCentroids, ...
                'squaredeuclidean');
            
            [nearestDistance, nearestLabels] = ...
                min( ...
                finalDistanceMatrix, ...
                [], ...
                2);
            
            % Recomputed nearest labels are canonical. Normally they are
            % identical to selectedResult.LabelsAll.
            medoidLabels = ...
                double(nearestLabels);
            
            sortedDistances = ...
                sort( ...
                finalDistanceMatrix, ...
                2, ...
                'ascend');
            
            if size(sortedDistances, 2) >= 2
                
                firstDistance = ...
                    sortedDistances(:, 1);
                
                secondDistance = ...
                    sortedDistances(:, 2);
                
                assignmentConfidence = ...
                    1 - ...
                    firstDistance ./ ...
                    max(secondDistance, eps);
                
            else
                
                assignmentConfidence = ...
                    ones(numberPixels, 1);
            end
            
            assignmentConfidence = ...
                max( ...
                0, ...
                min( ...
                1, ...
                assignmentConfidence));
            
            medianConfidence = ...
                median( ...
                assignmentConfidence, ...
                'omitnan');
            
            lowConfidenceFraction = ...
                mean( ...
                assignmentConfidence < 0.20, ...
                'omitnan');
            
            % =============================================================
            % 7. SPATIAL FRAGMENTATION QC
            %
            % These metrics describe the medoid map only.
            %
            % MedianLargestComponentFraction:
            %   for each cluster, fraction of its pixels contained in its
            %   largest connected component; then median across clusters.
            %
            % MedianIsolatedComponentFraction:
            %   for each cluster, fraction of connected components with
            %   <=2 pixels; then median across clusters.
            % =============================================================
            
            [medianLargestComponentFraction, ...
                medianIsolatedComponentFraction, ...
                totalConnectedComponents] = ...
                app.calculateCandidateSpatialQC( ...
                medoidLabels, ...
                pixelIdx, ...
                params.K);
            
            % =============================================================
            % 8. RETURN CANDIDATE RESULT
            % =============================================================
            
            candidateResult = struct();
            
            candidateResult.PCCount = ...
                pcCount;
            
            candidateResult.ExplainedVariance = ...
                NaN;
            
            candidateResult.Stage = ...
                stageName;
            
            candidateResult.NumberRuns = ...
                numberRuns;
            
            candidateResult.OverallMedianARI = ...
                stability.OverallMedianARI;
            
            candidateResult.MedoidMedianARI = ...
                stability.MedoidMedianARI;
            
            candidateResult.MedianConfidence = ...
                medianConfidence;
            
            candidateResult.LowConfidenceFraction = ...
                lowConfidenceFraction;
            
            candidateResult.MedianLargestComponentFraction = ...
                medianLargestComponentFraction;
            
            candidateResult.MedianIsolatedComponentFraction = ...
                medianIsolatedComponentFraction;
            
            candidateResult.TotalConnectedComponents = ...
                totalConnectedComponents;
            
            candidateResult.SelectedRun = ...
                selectedRun;
            
            candidateResult.SelectedSeed = ...
                stability.SelectedSeed;
            
            candidateResult.MedoidLabelsAll = ...
                uint16(medoidLabels);
            
            candidateResult.MedoidCentroids = ...
                medoidCentroids;
            
            candidateResult.NearestDistance = ...
                nearestDistance;
            
            candidateResult.AssignmentConfidence = ...
                assignmentConfidence;
            
            candidateResult.RunResults = ...
                runResults;
            
            candidateResult.PairwiseARI = ...
                stability.PairwiseARI;
            
            candidateResult.StabilityLevel = ...
                stability.StabilityLevel;
            
            candidateResult.Status = ...
                'Evaluated';
            
            candidateResult.SelectionReason = ...
                '';
            
        end


        function buildBalancedReferenceTrainingSet(app)
            
            % =============================================================
            % 1. FIND ACCEPTED ROIs
            % =============================================================
            
            acceptedBackground = ...
                find( ...
                strcmp( ...
                {app.ReferenceROIs.Class}, ...
                'Background') & ...
                [app.ReferenceROIs.UsablePixelCount] >= ...
                app.MinUsablePixelsPerROI);
            
            acceptedCell = ...
                find( ...
                strcmp( ...
                {app.ReferenceROIs.Class}, ...
                'Cell') & ...
                [app.ReferenceROIs.UsablePixelCount] >= ...
                app.MinUsablePixelsPerROI);
            
            if isempty(acceptedBackground)
                
                error( ...
                    'No Background ROI passed the minimum ROI QC.');
                
            end
            
            if isempty(acceptedCell)
                
                error( ...
                    'No Cell ROI passed the minimum ROI QC.');
                
            end
            
            % =============================================================
            % 2. COLLECT SAMPLES FROM EVERY ACCEPTED ROI
            % =============================================================
            
            backgroundSamples = ...
                app.collectBalancedSamplesForClass( ...
                acceptedBackground);
            
            cellSamples = ...
                app.collectBalancedSamplesForClass( ...
                acceptedCell);
            
            numberBackgroundAvailable = ...
                numel(backgroundSamples.PixelIdx);
            
            numberCellAvailable = ...
                numel(cellSamples.PixelIdx);
            
            if numberBackgroundAvailable < ...
                    app.MinTotalUsablePixelsPerClass
                
                error( ...
                    sprintf( ...
                    ['Only %d accepted Background pixels are available. ' ...
                    'At least %d are required.'], ...
                    numberBackgroundAvailable, ...
                    app.MinTotalUsablePixelsPerClass));
            end
            
            if numberCellAvailable < ...
                    app.MinTotalUsablePixelsPerClass
                
                error( ...
                    sprintf( ...
                    ['Only %d accepted Cell pixels are available. ' ...
                    'At least %d are required.'], ...
                    numberCellAvailable, ...
                    app.MinTotalUsablePixelsPerClass));
            end
            
            % =============================================================
            % 3. BALANCE BOTH CLASSES
            % =============================================================
            
            classTarget = ...
                min( ...
                [ ...
                numberBackgroundAvailable, ...
                numberCellAvailable, ...
                app.MaxPixelsPerClass]);
            
            if classTarget < ...
                    app.MinTotalUsablePixelsPerClass
                
                error( ...
                    'Too few balanced reference pixels remain for model training.');
            end
            
            backgroundSamples = ...
                app.reduceSamplesByROI( ...
                backgroundSamples, ...
                classTarget);
            
            cellSamples = ...
                app.reduceSamplesByROI( ...
                cellSamples, ...
                classTarget);
            
            % =============================================================
            % 4. STORE TRAINING SET
            % =============================================================
            
            app.BalancedTrainingPixelIdx = ...
                [ ...
                backgroundSamples.PixelIdx; ...
                cellSamples.PixelIdx];
            
            app.TrainingLabels = ...
                [ ...
                zeros( ...
                numel(backgroundSamples.PixelIdx), ...
                1); ...
                ones( ...
                numel(cellSamples.PixelIdx), ...
                1)];
            
            app.TrainingROIIDs = ...
                [ ...
                backgroundSamples.ROIID; ...
                cellSamples.ROIID];
            
            app.TrainingWeights = ...
                ones(size(app.TrainingLabels));
            
        end
        
        
        function samples = collectBalancedSamplesForClass( ...
                app, ...
                roiIndices)
            
            samples = struct();
            
            samples.PixelIdx = [];
            samples.ROIID = [];
            
            for roiIndex = roiIndices(:)'
                
                cleanMask = ...
                    app.ReferenceROIs(roiIndex).CleanMask;
                
                numberAvailable = ...
                    nnz(cleanMask);
                
                targetCount = ...
                    min( ...
                    [ ...
                    numberAvailable, ...
                    app.TargetPixelsPerROI, ...
                    app.MaxPixelsPerROI]);
                
                selectedPixelIdx = ...
                    app.sampleSpatiallyDistributedPixels( ...
                    cleanMask, ...
                    targetCount);
                
                samples.PixelIdx = ...
                    [ ...
                    samples.PixelIdx; ...
                    selectedPixelIdx(:)]; %#ok<AGROW>
                
                samples.ROIID = ...
                    [ ...
                    samples.ROIID; ...
                    repmat( ...
                    app.ReferenceROIs(roiIndex).ID, ...
                    numel(selectedPixelIdx), ...
                    1)]; %#ok<AGROW>
            end
            
        end
        
        

        function reduced = reduceSamplesByROI( ...
                app, ...
                samples, ...
                targetTotal)
            
            reduced = struct();
            
            reduced.PixelIdx = [];
            reduced.ROIID = [];
            
            roiIDs = ...
                unique(samples.ROIID, 'stable');
            
            numberROIs = ...
                numel(roiIDs);
            
            baseQuota = ...
                floor(targetTotal / numberROIs);
            
            remainingQuota = ...
                targetTotal - ...
                baseQuota * numberROIs;
            
            for roiNumber = 1:numberROIs
                
                currentROIID = ...
                    roiIDs(roiNumber);
                
                currentRows = ...
                    find(samples.ROIID == currentROIID);
                
                currentQuota = ...
                    baseQuota;
                
                if roiNumber <= remainingQuota
                    currentQuota = currentQuota + 1;
                end
                
                currentQuota = ...
                    min( ...
                    currentQuota, ...
                    numel(currentRows));
                
                if currentQuota < 1
                    continue
                end
                
                selectedRows = ...
                    unique( ...
                    round( ...
                    linspace( ...
                    1, ...
                    numel(currentRows), ...
                    currentQuota)));
                
                selectedRows = ...
                    currentRows(selectedRows);
                
                reduced.PixelIdx = ...
                    [ ...
                    reduced.PixelIdx; ...
                    samples.PixelIdx(selectedRows)]; %#ok<AGROW>
                
                reduced.ROIID = ...
                    [ ...
                    reduced.ROIID; ...
                    samples.ROIID(selectedRows)]; %#ok<AGROW>
            end
            
            % Fill any remaining capacity from unused samples.
            currentCount = ...
                numel(reduced.PixelIdx);
            
            if currentCount < targetTotal
                
                unusedMask = ...
                    ~ismember( ...
                    samples.PixelIdx, ...
                    reduced.PixelIdx);
                
                unusedRows = ...
                    find(unusedMask);
                
                extraCount = ...
                    min( ...
                    targetTotal - currentCount, ...
                    numel(unusedRows));
                
                if extraCount > 0
                    
                    extraRows = ...
                        unusedRows( ...
                        unique( ...
                        round( ...
                        linspace( ...
                        1, ...
                        numel(unusedRows), ...
                        extraCount))));
                    
                    reduced.PixelIdx = ...
                        [ ...
                        reduced.PixelIdx; ...
                        samples.PixelIdx(extraRows)];
                    
                    reduced.ROIID = ...
                        [ ...
                        reduced.ROIID; ...
                        samples.ROIID(extraRows)];
                end
            end
            
        end
        
        
        function [featureMatrix, featureNames] = buildPixelFeatureMatrix(app)
            
            if isempty(app.MetricPixelIdx)
                
                error('MetricPixelIdx is empty.');
            end
            
            numberPixels = ...
                numel(app.MetricPixelIdx);
            
            numberPC = ...
                min( ...
                [ ...
                app.PixelModelMaxPC, ...
                size(app.AllPCAScores, 2)]);
            
            featureMatrix = ...
                app.AllPCAScores(:, 1:numberPC);
            
            featureNames = ...
                arrayfun( ...
                @(pcNumber) sprintf('PC%d', pcNumber), ...
                1:numberPC, ...
                'UniformOutput', ...
                false);
            
            [ny, nx, numberWavenumbers] = ...
                size(app.RamanCube);
            
            allSpectra = ...
                reshape( ...
                double(app.RamanCube), ...
                ny * nx, ...
                numberWavenumbers);
            
            spectra = ...
                allSpectra(app.MetricPixelIdx, :);
            
            meanIntensity = ...
                mean(spectra, 2, 'omitnan');
            
            intensityStd = ...
                std(spectra, 0, 2, 'omitnan');
            
            maxMinusMedian = ...
                max(spectra, [], 2) - ...
                median(spectra, 2, 'omitnan');
            
            fingerprintBand = ...
                app.Wavenumber >= 700 & ...
                app.Wavenumber <= 1800;
            
            if nnz(fingerprintBand) >= 3
                
                fingerprintEnergy = ...
                    mean( ...
                    spectra(:, fingerprintBand), ...
                    2, ...
                    'omitnan');
                
            else
                
                fingerprintEnergy = ...
                    meanIntensity;
            end
            
            chBand = ...
                app.Wavenumber >= 2800 & ...
                app.Wavenumber <= 3050;
            
            if nnz(chBand) >= 3
                
                chEnergy = ...
                    mean( ...
                    spectra(:, chBand), ...
                    2, ...
                    'omitnan');
                
            else
                
                chEnergy = ...
                    meanIntensity;
            end
            
            clusteringConfidence = ...
                app.PixelConfidence(:);
            
            if numel(clusteringConfidence) ~= numberPixels
                
                clusteringConfidence = ...
                    zeros(numberPixels, 1);
            end
            
            featureMatrix = ...
                [ ...
                featureMatrix, ...
                meanIntensity, ...
                intensityStd, ...
                maxMinusMedian, ...
                fingerprintEnergy, ...
                chEnergy, ...
                clusteringConfidence];
            
            featureNames = ...
                [ ...
                featureNames, ...
                { ...
                'MeanIntensity', ...
                'IntensityStd', ...
                'MaxMinusMedian', ...
                'FingerprintEnergy', ...
                'CHEnergy', ...
                'ClusterConfidence'}];
            
            featureMatrix(~isfinite(featureMatrix)) = 0;
            
        end
        
        
        function trainPixelMembershipModel(app)
            
            [allFeatures, featureNames] = ...
                app.buildPixelFeatureMatrix();
            
            [isTrainingPixel, trainingRows] = ...
                ismember( ...
                app.BalancedTrainingPixelIdx, ...
                app.MetricPixelIdx);
            
            trainingRows = ...
                trainingRows(isTrainingPixel);
            
            trainingLabels = ...
                app.TrainingLabels(isTrainingPixel);
            
            trainingWeights = ...
                app.TrainingWeights(isTrainingPixel);
            
            trainingROIIDs = ...
                app.TrainingROIIDs(isTrainingPixel);
            
            if numel(unique(trainingLabels)) < 2
                
                error( ...
                    'Balanced training data does not contain both classes.');
            end
            
            trainingFeatures = ...
                allFeatures(trainingRows, :);
            
            featureMu = ...
                mean( ...
                trainingFeatures, ...
                1, ...
                'omitnan');
            
            featureSigma = ...
                std( ...
                trainingFeatures, ...
                0, ...
                1, ...
                'omitnan');
            
            featureSigma( ...
                ~isfinite(featureSigma) | ...
                featureSigma <= eps) = 1;
            
            trainingFeaturesZ = ...
                (trainingFeatures - featureMu) ./ ...
                featureSigma;
            
            allFeaturesZ = ...
                (allFeatures - featureMu) ./ ...
                featureSigma;
            
            % =============================================================
            % LOGISTIC MODEL
            % =============================================================
            
            try
                
                model = fitglm( ...
                    trainingFeaturesZ, ...
                    trainingLabels, ...
                    'Distribution', ...
                    'binomial', ...
                    'Link', ...
                    'logit', ...
                    'Weights', ...
                    trainingWeights);
                
                membershipScore = ...
                    predict( ...
                    model, ...
                    allFeaturesZ);
                
                modelType = ...
                    'Weighted logistic regression';
                
            catch logisticError
                
                warning( ...
                    'RamanCube:LogisticFallback', ...
                    'Logistic regression failed: %s', ...
                    logisticError.message);
                
                model = fitcdiscr( ...
                    trainingFeaturesZ, ...
                    trainingLabels, ...
                    'DiscrimType', ...
                    'pseudoLinear', ...
                    'Prior', ...
                    'uniform', ...
                    'Weights', ...
                    trainingWeights);
                
                [~, posteriorScore] = ...
                    predict( ...
                    model, ...
                    allFeaturesZ);
                
                classNames = ...
                    model.ClassNames;
                
                cellColumn = ...
                    find(classNames == 1, 1);
                
                membershipScore = ...
                    posteriorScore(:, cellColumn);
                
                modelType = ...
                    'Pseudo-linear discriminant fallback';
            end
            
            membershipScore = ...
                max( ...
                0, ...
                min( ...
                1, ...
                membershipScore));
            
            app.PixelModel = model;
            app.PixelModelType = modelType;
            
            app.PixelFeatureNames = featureNames;
            app.PixelFeatureMu = featureMu;
            app.PixelFeatureSigma = featureSigma;
            
            [ny, nx] = ...
                size(app.ValidMask2D);
            
            membershipMap = ...
                nan(ny, nx);
            
            membershipMap(app.MetricPixelIdx) = ...
                membershipScore;
            
            membershipMap(~app.ValidMask2D) = NaN;
            
            uncertaintyMap = ...
                nan(ny, nx);
            
            uncertaintyValues = ...
                1 - abs( ...
                2 .* membershipScore - 1);
            
            uncertaintyMap(app.MetricPixelIdx) = ...
                uncertaintyValues;
            
            reliabilityMap = ...
                nan(ny, nx);
            
            reliabilityValues = ...
                (1 - uncertaintyValues) .* ...
                max( ...
                0, ...
                min(1, app.PixelConfidence(:)));
            
            reliabilityMap(app.MetricPixelIdx) = ...
                reliabilityValues;
            
            app.CellMembershipMap = membershipMap;
            app.PixelUncertaintyMap = uncertaintyMap;
            app.PixelReliabilityMap = reliabilityMap;
            
            app.computeGroupedPixelValidation( ...
                trainingFeaturesZ, ...
                trainingLabels, ...
                trainingWeights, ...
                trainingROIIDs);
            
        end
        
        
        function computeGroupedPixelValidation( ...
                app, ...
                trainingFeatures, ...
                trainingLabels, ...
                trainingWeights, ...
                trainingROIIDs)
            
            metrics = struct();
            
            metrics.ValidationMethod = ...
                'Unavailable';
            
            metrics.BalancedAccuracy = ...
                NaN;
            
            metrics.CellSensitivity = ...
                NaN;
            
            metrics.BackgroundSpecificity = ...
                NaN;
            
            backgroundROIs = ...
                unique( ...
                trainingROIIDs(trainingLabels == 0));
            
            cellROIs = ...
                unique( ...
                trainingROIIDs(trainingLabels == 1));
            
            numberFolds = ...
                min( ...
                [ ...
                5, ...
                numel(backgroundROIs), ...
                numel(cellROIs)]);
            
            if numberFolds < 2
                
                metrics.ValidationMethod = ...
                    'Not available: fewer than 2 accepted ROIs per class';
                
                app.PixelAssessmentMetrics.Validation = ...
                    metrics;
                
                return
            end
            
            foldID = ...
                zeros(size(trainingLabels));
            
            for roiNumber = 1:numel(backgroundROIs)
                
                currentFold = ...
                    mod(roiNumber - 1, numberFolds) + 1;
                
                foldID( ...
                    trainingROIIDs == backgroundROIs(roiNumber)) = ...
                    currentFold;
            end
            
            for roiNumber = 1:numel(cellROIs)
                
                currentFold = ...
                    mod(roiNumber - 1, numberFolds) + 1;
                
                foldID( ...
                    trainingROIIDs == cellROIs(roiNumber)) = ...
                    currentFold;
            end
            
            predictedLabels = ...
                nan(size(trainingLabels));
            
            for foldNumber = 1:numberFolds
                
                testMask = ...
                    foldID == foldNumber;
                
                trainMask = ...
                    ~testMask;
                
                if numel(unique(trainingLabels(trainMask))) < 2 || ...
                        numel(unique(trainingLabels(testMask))) < 2
                    
                    continue
                end
                
                try
                    
                    foldModel = fitglm( ...
                        trainingFeatures(trainMask, :), ...
                        trainingLabels(trainMask), ...
                        'Distribution', ...
                        'binomial', ...
                        'Link', ...
                        'logit', ...
                        'Weights', ...
                        trainingWeights(trainMask));
                    
                    foldScore = ...
                        predict( ...
                        foldModel, ...
                        trainingFeatures(testMask, :));
                    
                catch
                    
                    foldModel = fitcdiscr( ...
                        trainingFeatures(trainMask, :), ...
                        trainingLabels(trainMask), ...
                        'DiscrimType', ...
                        'pseudoLinear', ...
                        'Prior', ...
                        'uniform');
                    
                    [~, foldPosterior] = ...
                        predict( ...
                        foldModel, ...
                        trainingFeatures(testMask, :));
                    
                    cellColumn = ...
                        find(foldModel.ClassNames == 1, 1);
                    
                    foldScore = ...
                        foldPosterior(:, cellColumn);
                end
                
                predictedLabels(testMask) = ...
                    foldScore >= 0.5;
            end
            
            validPrediction = ...
                isfinite(predictedLabels);
            
            yTrue = ...
                trainingLabels(validPrediction);
            
            yPred = ...
                predictedLabels(validPrediction);
            
            cellSensitivity = ...
                mean( ...
                yPred(yTrue == 1) == 1);
            
            backgroundSpecificity = ...
                mean( ...
                yPred(yTrue == 0) == 0);
            
            balancedAccuracy = ...
                mean( ...
                [ ...
                cellSensitivity, ...
                backgroundSpecificity], ...
                'omitnan');
            
            metrics.ValidationMethod = ...
                sprintf( ...
                '%d-fold grouped-by-ROI validation', ...
                numberFolds);
            
            metrics.BalancedAccuracy = ...
                balancedAccuracy;
            
            metrics.CellSensitivity = ...
                cellSensitivity;
            
            metrics.BackgroundSpecificity = ...
                backgroundSpecificity;
            
            app.PixelAssessmentMetrics.Validation = ...
                metrics;
            
        end
        
        
        function buildFinalPixelMasks(app)
            
            validMask = ...
                app.ValidMask2D & ...
                isfinite(app.CellMembershipMap);
            
            backgroundMask = ...
                validMask & ...
                app.CellMembershipMap <= ...
                app.PixelBackgroundThreshold;
            
            cellMask = ...
                validMask & ...
                app.CellMembershipMap >= ...
                app.PixelCellThreshold;
            
            uncertainMask = ...
                validMask & ...
                ~backgroundMask & ...
                ~cellMask;
            
            % Do not allow overlap.
            backgroundMask(cellMask) = false;
            uncertainMask(backgroundMask | cellMask) = false;
            
            finalClassMap = ...
                zeros(size(validMask));
            
            % 0 = invalid/artifact
            % 1 = confident background
            % 2 = uncertain/intermediate
            % 3 = confident cell
            finalClassMap(backgroundMask) = 1;
            finalClassMap(uncertainMask) = 2;
            finalClassMap(cellMask) = 3;
            
            app.FinalBackgroundMask2D = ...
                backgroundMask;
            
            app.FinalCellMask2D = ...
                cellMask;
            
            app.FinalUncertainMask2D = ...
                uncertainMask;
            
            app.FinalClassMap = ...
                finalClassMap;
            
        end
        
        
        % function computeClusterPixelPurity(app)
        %
        %     for clusterIndex = 1:numel(app.Clusters)
        %
        %         C = ...
        %             app.Clusters(clusterIndex);
        %
        %         if ~C.Active || ...
        %                 isempty(C.PixelIdx)
        %
        %             continue
        %         end
        %
        %         clusterMembership = ...
        %             app.CellMembershipMap(C.PixelIdx);
        %
        %         clusterUncertainty = ...
        %             app.PixelUncertaintyMap(C.PixelIdx);
        %
        %         validValues = ...
        %             isfinite(clusterMembership);
        %
        %         clusterMembership = ...
        %             clusterMembership(validValues);
        %
        %         clusterUncertainty = ...
        %             clusterUncertainty(validValues);
        %
        %         if isempty(clusterMembership)
        %             continue
        %         end
        %
        %         backgroundFraction = ...
        %             mean( ...
        %             clusterMembership <= ...
        %             app.PixelBackgroundThreshold);
        %
        %         cellFraction = ...
        %             mean( ...
        %             clusterMembership >= ...
        %             app.PixelCellThreshold);
        %
        %         uncertainFraction = ...
        %             mean( ...
        %             clusterMembership > ...
        %             app.PixelBackgroundThreshold & ...
        %             clusterMembership < ...
        %             app.PixelCellThreshold);
        %
        %         M = ...
        %             app.Clusters(clusterIndex).Metrics;
        %
        %         M.EstimatedBackgroundPurity = ...
        %             backgroundFraction;
        %
        %         M.EstimatedCellPurity = ...
        %             cellFraction;
        %
        %         M.EstimatedCellContamination = ...
        %             cellFraction;
        %
        %         M.UncertainPixelFraction = ...
        %             uncertainFraction;
        %
        %         M.MeanCellMembership = ...
        %             mean(clusterMembership, 'omitnan');
        %
        %         M.MedianCellMembership = ...
        %             median(clusterMembership, 'omitnan');
        %
        %         M.P90CellMembership = ...
        %             prctile(clusterMembership, 90);
        %
        %         M.MedianPixelUncertainty = ...
        %             median(clusterUncertainty, 'omitnan');
        %
        %         if backgroundFraction >= 0.95 && ...
        %                 cellFraction <= 0.01
        %
        %             M.PixelRecommendation = ...
        %                 'High-purity background cluster.';
        %
        %         elseif cellFraction >= 0.80
        %
        %             M.PixelRecommendation = ...
        %                 'Cell-dominant cluster.';
        %
        %         elseif uncertainFraction >= 0.25
        %
        %             M.PixelRecommendation = ...
        %                 'High uncertainty; review or split.';
        %
        %         elseif backgroundFraction >= 0.70
        %
        %             M.PixelRecommendation = ...
        %                 'Background-dominant but mixed; use pixel mask only.';
        %
        %         elseif cellFraction >= 0.50
        %
        %             M.PixelRecommendation = ...
        %                 'Cell-enriched but not pure.';
        %
        %         else
        %
        %             M.PixelRecommendation = ...
        %                 'Mixed cluster; pixel-level assignment required.';
        %         end
        %
        %         app.Clusters(clusterIndex).Metrics = ...
        %             M;
        %     end
        %
        % end
        
        function computeClusterPixelEvidence(app)
            
            % =============================================================
            % CLUSTER EVIDENCE ASSESSMENT
            %
            % This function deliberately separates:
            %
            %   1. Direct ground-truth evidence:
            %      overlap with cleaned user reference ROIs.
            %
            %   2. Model-supported composition:
            %      fraction of cluster pixels assigned to conservative
            %      Background / Intermediate / Cell score regions.
            %
            % No whole-cluster "purity" claim is made.
            % =============================================================
            
            if isempty(app.Clusters) || ...
                    isempty(app.CellMembershipMap)
                
                return
            end
            
            if isempty(app.BackgroundReferenceMask2D)
                
                backgroundReferenceMask = ...
                    false(size(app.ValidMask2D));
                
            else
                
                backgroundReferenceMask = ...
                    logical(app.BackgroundReferenceMask2D);
            end
            
            if isempty(app.CellReferenceMask2D)
                
                cellReferenceMask = ...
                    false(size(app.ValidMask2D));
                
            else
                
                cellReferenceMask = ...
                    logical(app.CellReferenceMask2D);
            end
            
            for clusterIndex = 1:numel(app.Clusters)
                
                C = ...
                    app.Clusters(clusterIndex);
                
                if ~C.Active || ...
                        isempty(C.PixelIdx)
                    
                    continue
                end
                
                clusterPixelIdx = ...
                    double(C.PixelIdx(:));
                
                clusterPixelCount = ...
                    numel(clusterPixelIdx);
                
                % =========================================================
                % 1. MODEL-SUPPORTED COMPOSITION
                % =========================================================
                
                membershipValues = ...
                    app.CellMembershipMap(clusterPixelIdx);
                
                validMembership = ...
                    isfinite(membershipValues);
                
                membershipValues = ...
                    membershipValues(validMembership);
                
                modelEvaluatedCount = ...
                    numel(membershipValues);
                
                if modelEvaluatedCount > 0
                    
                    confidentBackgroundFraction = ...
                        mean( ...
                        membershipValues <= ...
                        app.PixelBackgroundThreshold);
                    
                    confidentCellFraction = ...
                        mean( ...
                        membershipValues >= ...
                        app.PixelCellThreshold);
                    
                    intermediateFraction = ...
                        mean( ...
                        membershipValues > ...
                        app.PixelBackgroundThreshold & ...
                        membershipValues < ...
                        app.PixelCellThreshold);
                    
                    meanMembership = ...
                        mean( ...
                        membershipValues, ...
                        'omitnan');
                    
                    medianMembership = ...
                        median( ...
                        membershipValues, ...
                        'omitnan');
                    
                    p10Membership = ...
                        prctile( ...
                        membershipValues, ...
                        10);
                    
                    p90Membership = ...
                        prctile( ...
                        membershipValues, ...
                        90);
                    
                else
                    
                    confidentBackgroundFraction = NaN;
                    confidentCellFraction = NaN;
                    intermediateFraction = NaN;
                    
                    meanMembership = NaN;
                    medianMembership = NaN;
                    p10Membership = NaN;
                    p90Membership = NaN;
                end
                
                % =========================================================
                % 2. DIRECT GROUND-TRUTH SUPPORT
                %
                % These counts are based only on cleaned reference pixels
                % drawn by the user.
                % =========================================================
                
                gtBackgroundCount = ...
                    nnz( ...
                    backgroundReferenceMask(clusterPixelIdx));
                
                gtCellCount = ...
                    nnz( ...
                    cellReferenceMask(clusterPixelIdx));
                
                gtLabeledCount = ...
                    gtBackgroundCount + ...
                    gtCellCount;
                
                gtCoverage = ...
                    gtLabeledCount / ...
                    max(1, clusterPixelCount);
                
                if gtLabeledCount > 0
                    
                    gtBackgroundFraction = ...
                        gtBackgroundCount / ...
                        gtLabeledCount;
                    
                    gtCellFraction = ...
                        gtCellCount / ...
                        gtLabeledCount;
                    
                else
                    
                    gtBackgroundFraction = NaN;
                    gtCellFraction = NaN;
                end
                
                sufficientGT = ...
                    gtLabeledCount >= ...
                    app.ClusterGTEvidenceMinPixels && ...
                    gtCoverage >= ...
                    app.ClusterGTEvidenceMinCoverage;
                
                % =========================================================
                % 3. EVIDENCE STATUS
                %
                % Ground-truth-supported labels require:
                %   - sufficient GT count;
                %   - sufficient GT coverage;
                %   - high GT agreement;
                %   - compatible model-supported composition.
                %
                % Otherwise the interpretation explicitly states that it is
                % model-supported only or insufficiently supported.
                % =========================================================
                
                evidenceStatus = ...
                    'Insufficient ground-truth support';
                
                evidenceBasis = ...
                    ['Too few cleaned reference pixels intersect this cluster ' ...
                    'to support a whole-cluster interpretation.'];
                
                recommendedAction = ...
                    ['Preserve the cluster and review it at pixel level. ' ...
                    'Add independent reference ROIs if stronger evidence is needed.'];
                
                if sufficientGT
                    
                    if gtBackgroundFraction >= ...
                            app.ClusterGTAgreementThreshold && ...
                            confidentBackgroundFraction >= ...
                            app.ClusterModelBackgroundSupport && ...
                            confidentCellFraction <= ...
                            app.ClusterOpposingFractionMax
                        
                        evidenceStatus = ...
                            'Ground-truth-supported background';
                        
                        evidenceBasis = ...
                            ['Ground-truth overlap is predominantly Background, ' ...
                            'and the full cluster is strongly background-supported ' ...
                            'by the pixel model.'];
                        
                        recommendedAction = ...
                            ['Confident-background pixels may be removed. ' ...
                            'Intermediate pixels must remain preserved.'];
                        
                    elseif gtCellFraction >= ...
                            app.ClusterGTAgreementThreshold && ...
                            confidentCellFraction >= ...
                            app.ClusterModelCellSupport && ...
                            confidentBackgroundFraction <= ...
                            app.ClusterOpposingFractionMax
                        
                        evidenceStatus = ...
                            'Ground-truth-supported cell';
                        
                        evidenceBasis = ...
                            ['Ground-truth overlap is predominantly Cell, ' ...
                            'and the full cluster is cell-supported by the ' ...
                            'pixel model.'];
                        
                        recommendedAction = ...
                            ['Preserve the cluster. Use the confident-cell mask ' ...
                            'for downstream cell-spectrum extraction.'];
                        
                    elseif gtBackgroundCount > 0 && ...
                            gtCellCount > 0
                        
                        evidenceStatus = ...
                            'Ground-truth-supported mixed';
                        
                        evidenceBasis = ...
                            ['Both Background and Cell reference pixels occur ' ...
                            'inside this cluster.'];
                        
                        recommendedAction = ...
                            ['Do not assign the complete cluster to one biological ' ...
                            'class. Use pixel-level masks or split the cluster.'];
                        
                    elseif intermediateFraction >= 0.25
                        
                        evidenceStatus = ...
                            'Intermediate / mixed';
                        
                        evidenceBasis = ...
                            ['Ground-truth support exists, but a substantial fraction ' ...
                            'of cluster pixels lies between the conservative ' ...
                            'Background and Cell thresholds.'];
                        
                        recommendedAction = ...
                            ['Preserve intermediate pixels. Consider splitting the ' ...
                            'cluster only after inspecting its membership distribution.'];
                        
                    else
                        
                        evidenceStatus = ...
                            'Ground-truth/model disagreement';
                        
                        evidenceBasis = ...
                            ['Ground-truth overlap and whole-cluster model composition ' ...
                            'do not support the same interpretation.'];
                        
                        recommendedAction = ...
                            ['Do not remove this cluster automatically. Review ROI ' ...
                            'placement, spectral heterogeneity, and cluster boundaries.'];
                    end
                    
                else
                    
                    if confidentBackgroundFraction >= ...
                            app.ClusterModelBackgroundSupport && ...
                            confidentCellFraction <= ...
                            app.ClusterOpposingFractionMax
                        
                        evidenceStatus = ...
                            'Model-supported background only';
                        
                        evidenceBasis = ...
                            ['The pixel model strongly supports Background, but ' ...
                            'ground-truth coverage is insufficient for a direct ' ...
                            'whole-cluster claim.'];
                        
                        recommendedAction = ...
                            ['Only confident-background pixels may be considered ' ...
                            'for removal. Preserve intermediate pixels.'];
                        
                    elseif confidentCellFraction >= ...
                            app.ClusterModelCellSupport && ...
                            confidentBackgroundFraction <= ...
                            app.ClusterOpposingFractionMax
                        
                        evidenceStatus = ...
                            'Model-supported cell only';
                        
                        evidenceBasis = ...
                            ['The pixel model supports Cell, but ground-truth coverage ' ...
                            'is insufficient for a direct whole-cluster claim.'];
                        
                        recommendedAction = ...
                            ['Preserve the cluster. Add more Cell reference ROIs ' ...
                            'before treating it as ground-truth-supported cell.'];
                        
                    elseif intermediateFraction >= 0.25
                        
                        evidenceStatus = ...
                            'Intermediate / mixed';
                        
                        evidenceBasis = ...
                            ['A substantial fraction of cluster pixels lies inside ' ...
                            'the conservative intermediate score interval.'];
                        
                        recommendedAction = ...
                            ['Preserve the cluster and use pixel-level classification. ' ...
                            'Do not remove it as a complete cluster.'];
                    end
                end
                
                % =========================================================
                % 4. STORE METRICS
                % =========================================================
                
                M = ...
                    app.Clusters(clusterIndex).Metrics;
                
                if isempty(M) || ...
                        ~isstruct(M)
                    
                    M = struct();
                end
                
                % Direct GT evidence
                M.GTBackgroundCount = ...
                    gtBackgroundCount;
                
                M.GTCellCount = ...
                    gtCellCount;
                
                M.GTLabeledCount = ...
                    gtLabeledCount;
                
                M.GTCoverage = ...
                    gtCoverage;
                
                M.GTBackgroundFractionWithinLabeled = ...
                    gtBackgroundFraction;
                
                M.GTCellFractionWithinLabeled = ...
                    gtCellFraction;
                
                M.HasSufficientGroundTruth = ...
                    sufficientGT;
                
                % Model-supported composition
                M.ModelEvaluatedPixelCount = ...
                    modelEvaluatedCount;
                
                M.ConfidentBackgroundFraction = ...
                    confidentBackgroundFraction;
                
                M.IntermediateFraction = ...
                    intermediateFraction;
                
                M.ConfidentCellFraction = ...
                    confidentCellFraction;
                
                M.MeanCellMembership = ...
                    meanMembership;
                
                M.MedianCellMembership = ...
                    medianMembership;
                
                M.P10CellMembership = ...
                    p10Membership;
                
                M.P90CellMembership = ...
                    p90Membership;
                
                % Interpretation
                M.EvidenceStatus = ...
                    evidenceStatus;
                
                M.EvidenceBasis = ...
                    evidenceBasis;
                
                M.RecommendedAction = ...
                    recommendedAction;
                
                app.Clusters(clusterIndex).Metrics = ...
                    M;
                
            end
            
        end
        
        % function displayPixelAssessmentResults(app)
        %
        %     if ~app.PixelAssessmentReady
        %         return
        %     end
        %
        %     % =============================================================
        %     % AXIS 2: CELL MEMBERSHIP
        %     % =============================================================
        %
        %     app.resetImageAxis(app.UIAxes_2);
        %
        %     membershipHandle = ...
        %         imagesc( ...
        %         app.UIAxes_2, ...
        %         app.CellMembershipMap, ...
        %         [0 1]);
        %
        %     membershipHandle.AlphaData = ...
        %         double(isfinite(app.CellMembershipMap));
        %
        %     axis(app.UIAxes_2, 'image');
        %
        %     app.UIAxes_2.YDir = ...
        %         'normal';
        %
        %     colormap(app.UIAxes_2, turbo(256));
        %
        %     colorbar(app.UIAxes_2);
        %
        %     hold(app.UIAxes_2, 'on');
        %
        %     contour( ...
        %         app.UIAxes_2, ...
        %         app.FinalCellMask2D, ...
        %         [0.5 0.5], ...
        %         'w', ...
        %         'LineWidth', ...
        %         1.1);
        %
        %     contour( ...
        %         app.UIAxes_2, ...
        %         app.FinalBackgroundMask2D, ...
        %         [0.5 0.5], ...
        %         'k', ...
        %         'LineWidth', ...
        %         0.7);
        %
        %     hold(app.UIAxes_2, 'off');
        %
        %     title( ...
        %         app.UIAxes_2, ...
        %         { ...
        %         'Cell membership score', ...
        %         sprintf( ...
        %         'Background <= %.2f | Cell >= %.2f', ...
        %         app.PixelBackgroundThreshold, ...
        %         app.PixelCellThreshold)}, ...
        %         'Interpreter', ...
        %         'none');
        %
        %     % =============================================================
        %     % AXIS 4: UNCERTAINTY
        %     % =============================================================
        %
        %     app.resetImageAxis(app.UIAxes_4);
        %
        %     uncertaintyHandle = ...
        %         imagesc( ...
        %         app.UIAxes_4, ...
        %         app.PixelUncertaintyMap, ...
        %         [0 1]);
        %
        %     uncertaintyHandle.AlphaData = ...
        %         double(isfinite(app.PixelUncertaintyMap));
        %
        %     axis(app.UIAxes_4, 'image');
        %
        %     app.UIAxes_4.YDir = ...
        %         'normal';
        %
        %     colormap(app.UIAxes_4, hot(256));
        %
        %     colorbar(app.UIAxes_4);
        %
        %     hold(app.UIAxes_4, 'on');
        %
        %     contour( ...
        %         app.UIAxes_4, ...
        %         app.FinalUncertainMask2D, ...
        %         [0.5 0.5], ...
        %         'c', ...
        %         'LineWidth', ...
        %         1.3);
        %
        %     hold(app.UIAxes_4, 'off');
        %
        %     title( ...
        %         app.UIAxes_4, ...
        %         { ...
        %         'Pixel uncertainty', ...
        %         '0 = confident | 1 = maximally uncertain'}, ...
        %         'Interpreter', ...
        %         'none');
        %
        % end
        
        
        function displayClusterEvidenceOnAxis4(app)
            
            if ~app.PixelAssessmentReady
                return
            end
            
            selectedIDs = ...
                app.SelectedClusterIDs;
            
            % =============================================================
            % ONE CLUSTER SELECTED:
            % SHOW MEMBERSHIP HISTOGRAM
            % =============================================================
            
            if numel(selectedIDs) == 1
                
                clusterIndex = ...
                    app.findClusterIndexByID(selectedIDs(1));
                
                if isempty(clusterIndex)
                    return
                end
                
                C = ...
                    app.Clusters(clusterIndex);
                
                membershipValues = ...
                    app.CellMembershipMap(C.PixelIdx);
                
                membershipValues = ...
                    membershipValues(isfinite(membershipValues));
                
                cla(app.UIAxes_4, 'reset');
                
                app.UIAxes_4.Box = ...
                    'on';
                
                app.UIAxes_4.XGrid = ...
                    'on';
                
                app.UIAxes_4.YGrid = ...
                    'off';
                
                if isempty(membershipValues)
                    
                    text( ...
                        app.UIAxes_4, ...
                        0.5, ...
                        0.5, ...
                        'No membership values available', ...
                        'Units', ...
                        'normalized', ...
                        'HorizontalAlignment', ...
                        'center');
                    
                    return
                end
                
                histogram( ...
                    app.UIAxes_4, ...
                    membershipValues, ...
                    'BinEdges', ...
                    linspace(0, 1, 26), ...
                    'Normalization', ...
                    'probability');
                
                hold(app.UIAxes_4, 'on');
                
                xline( ...
                    app.UIAxes_4, ...
                    app.PixelBackgroundThreshold, ...
                    '--', ...
                    'Background threshold', ...
                    'LineWidth', ...
                    1.5, ...
                    'LabelVerticalAlignment', ...
                    'bottom');
                
                xline( ...
                    app.UIAxes_4, ...
                    app.PixelCellThreshold, ...
                    '--', ...
                    'Cell threshold', ...
                    'LineWidth', ...
                    1.5, ...
                    'LabelVerticalAlignment', ...
                    'bottom');
                
                % ---------------------------------------------------------
                % Ground-truth sample positions inside selected cluster
                % ---------------------------------------------------------
                
                yLimits = ...
                    app.UIAxes_4.YLim;
                
                yTop = ...
                    yLimits(2);
                
                backgroundGTPixels = ...
                    C.PixelIdx( ...
                    app.BackgroundReferenceMask2D(C.PixelIdx));
                
                cellGTPixels = ...
                    C.PixelIdx( ...
                    app.CellReferenceMask2D(C.PixelIdx));
                
                backgroundGTMembership = ...
                    app.CellMembershipMap(backgroundGTPixels);
                
                backgroundGTMembership = ...
                    backgroundGTMembership( ...
                    isfinite(backgroundGTMembership));
                
                cellGTMembership = ...
                    app.CellMembershipMap(cellGTPixels);
                
                cellGTMembership = ...
                    cellGTMembership( ...
                    isfinite(cellGTMembership));
                
                if ~isempty(backgroundGTMembership)
                    
                    scatter( ...
                        app.UIAxes_4, ...
                        backgroundGTMembership, ...
                        repmat( ...
                        0.92 * yTop, ...
                        size(backgroundGTMembership)), ...
                        18, ...
                        [0.10 0.65 1.00], ...
                        'filled', ...
                        'MarkerFaceAlpha', ...
                        0.65);
                end
                
                if ~isempty(cellGTMembership)
                    
                    scatter( ...
                        app.UIAxes_4, ...
                        cellGTMembership, ...
                        repmat( ...
                        0.82 * yTop, ...
                        size(cellGTMembership)), ...
                        18, ...
                        [0.15 0.90 0.25], ...
                        'filled', ...
                        'MarkerFaceAlpha', ...
                        0.65);
                end
                
                hold(app.UIAxes_4, 'off');
                
                xlim(app.UIAxes_4, [0 1]);
                
                xlabel( ...
                    app.UIAxes_4, ...
                    'Model cell-membership score, P(cell)');
                
                ylabel( ...
                    app.UIAxes_4, ...
                    'Fraction of cluster pixels');
                
                title( ...
                    app.UIAxes_4, ...
                    { ...
                    [C.Name ' — membership distribution'], ...
                    'Cyan dots = Background GT | Green dots = Cell GT'}, ...
                    'Interpreter', ...
                    'none');
                
                return
            end
            
            % =============================================================
            % NO SINGLE CLUSTER SELECTED:
            % SHOW STACKED CLUSTER COMPOSITION
            % =============================================================
            
            activeIndices = ...
                find([app.Clusters.Active]);
            
            validIndices = [];
            
            for clusterIndex = activeIndices
                
                if isfield( ...
                        app.Clusters(clusterIndex).Metrics, ...
                        'ConfidentBackgroundFraction')
                    
                    validIndices(end + 1) = ...
                        clusterIndex; %#ok<AGROW>
                end
            end
            
            cla(app.UIAxes_4, 'reset');
            
            if isempty(validIndices)
                
                text( ...
                    app.UIAxes_4, ...
                    0.5, ...
                    0.5, ...
                    'No cluster evidence is available', ...
                    'Units', ...
                    'normalized', ...
                    'HorizontalAlignment', ...
                    'center');
                
                return
            end
            
            numberClusters = ...
                numel(validIndices);
            
            composition = ...
                zeros(numberClusters, 3);
            
            clusterNames = ...
                cell(numberClusters, 1);
            
            for rowNumber = 1:numberClusters
                
                clusterIndex = ...
                    validIndices(rowNumber);
                
                C = ...
                    app.Clusters(clusterIndex);
                
                M = ...
                    C.Metrics;
                
                composition(rowNumber, :) = ...
                    [ ...
                    M.ConfidentBackgroundFraction, ...
                    M.IntermediateFraction, ...
                    M.ConfidentCellFraction];
                
                clusterNames{rowNumber} = ...
                    sprintf( ...
                    '%s (n=%d)', ...
                    C.Name, ...
                    numel(C.PixelIdx));
            end
            
            barHandles = ...
                barh( ...
                app.UIAxes_4, ...
                composition, ...
                'stacked');
            
            barHandles(1).FaceColor = ...
                [0.12 0.48 1.00];
            
            barHandles(2).FaceColor = ...
                [1.00 0.70 0.05];
            
            barHandles(3).FaceColor = ...
                [0.10 0.90 0.25];
            
            app.UIAxes_4.Box = ...
                'on';
            
            app.UIAxes_4.XGrid = ...
                'on';
            
            app.UIAxes_4.YGrid = ...
                'off';
            
            app.UIAxes_4.YTick = ...
                1:numberClusters;
            
            app.UIAxes_4.YTickLabel = ...
                clusterNames;
            
            app.UIAxes_4.YDir = ...
                'reverse';
            
            xlim(app.UIAxes_4, [0 1]);
            
            xlabel( ...
                app.UIAxes_4, ...
                'Fraction of model-evaluated cluster pixels');
            
            title( ...
                app.UIAxes_4, ...
                { ...
                'Cluster model-supported composition', ...
                'Blue = background | Yellow = intermediate | Green = cell'}, ...
                'Interpreter', ...
                'none');
            
            legend( ...
                app.UIAxes_4, ...
                { ...
                'Confident background', ...
                'Intermediate / preserved', ...
                'Confident cell'}, ...
                'Location', ...
                'southoutside', ...
                'Orientation', ...
                'horizontal', ...
                'Interpreter', ...
                'none');
            
        end
        
        
        function signature = makeClusterPixelSignature(app, pixelIdx)
            
            %#ok<INUSD>
            
            pixelIdx = ...
                sort(double(pixelIdx(:)));
            
            if isempty(pixelIdx)
                
                signature = ...
                    'empty';
                
                return
            end
            
            pixelCount = ...
                numel(pixelIdx);
            
            pixelSum = ...
                sum(pixelIdx);
            
            pixelSquareSum = ...
                sum(pixelIdx .^ 2);
            
            firstPixel = ...
                pixelIdx(1);
            
            lastPixel = ...
                pixelIdx(end);
            
            signature = ...
                sprintf( ...
                'n%d_sum%.0f_sq%.0f_first%.0f_last%.0f', ...
                pixelCount, ...
                pixelSum, ...
                pixelSquareSum, ...
                firstPixel, ...
                lastPixel);
            
        end
        
        function assessment = updateComponentAssessmentStaleState( ...
                app, ...
                assessment)
            
            assessment.IsStale = ...
                true;
            
            clusterIndex = ...
                app.findClusterIndexByID( ...
                assessment.ClusterID);
            
            if isempty(clusterIndex)
                return
            end
            
            C = ...
                app.Clusters(clusterIndex);
            
            if ~C.Active
                return
            end
            
            currentSignature = ...
                app.makeClusterPixelSignature( ...
                C.PixelIdx);
            
            assessment.IsStale = ...
                ~strcmp( ...
                assessment.ClusterPixelSignature, ...
                currentSignature);
            
        end


        function normalizedSpectra = ...
                normalizeSpectraForComponentAssessment( ...
                app, ...
                spectra)
            
            %#ok<INUSD>
            
            spectra = ...
                double(spectra);
            
            if isempty(spectra)
                
                normalizedSpectra = [];
                return
            end
            
            if isvector(spectra)
                
                spectra = ...
                    reshape(spectra, 1, []);
            end
            
            % -------------------------------------------------------------
            % 1. Replace non-finite values row-by-row
            % -------------------------------------------------------------
            
            for rowNumber = 1:size(spectra, 1)
                
                currentSpectrum = ...
                    spectra(rowNumber, :);
                
                finiteMask = ...
                    isfinite(currentSpectrum);
                
                if ~any(finiteMask)
                    
                    currentSpectrum(:) = 0;
                    
                else
                    
                    fillValue = ...
                        median( ...
                        currentSpectrum(finiteMask), ...
                        'omitnan');
                    
                    currentSpectrum(~finiteMask) = ...
                        fillValue;
                end
                
                spectra(rowNumber, :) = ...
                    currentSpectrum;
            end
            
            % -------------------------------------------------------------
            % 2. Remove constant offset
            %
            % This is intentionally mild. It is not a full baseline
            % correction.
            % -------------------------------------------------------------
            
            rowMedian = ...
                median( ...
                spectra, ...
                2, ...
                'omitnan');
            
            spectra = ...
                spectra - rowMedian;
            
            % -------------------------------------------------------------
            % 3. L2 normalization
            % -------------------------------------------------------------
            
            rowNorm = ...
                sqrt( ...
                sum( ...
                spectra .^ 2, ...
                2));
            
            invalidNorm = ...
                ~isfinite(rowNorm) | ...
                rowNorm <= eps;
            
            rowNorm(invalidNorm) = ...
                1;
            
            normalizedSpectra = ...
                spectra ./ rowNorm;
            
            normalizedSpectra( ...
                ~isfinite(normalizedSpectra)) = ...
                0;
            
        end
        
        
        function [referenceWN, referenceIntensity] = ...
                readReferenceSpectrumTXT( ...
                app, ...
                filePath)
            
            %#ok<INUSD>
            
            filePath = ...
                char(string(filePath));
            
            try
                
                numericData = ...
                    readmatrix(filePath);
                
            catch ME
                
                error( ...
                    'Could not read reference file:\n%s\n\n%s', ...
                    filePath, ...
                    ME.message);
            end
            
            if isempty(numericData) || ...
                    ~isnumeric(numericData) || ...
                    size(numericData, 2) < 2
                
                error( ...
                    ['Reference TXT must contain at least two numeric columns:' ...
                    newline ...
                    'column 1 = wavenumber, column 2 = intensity.']);
            end
            
            referenceWN = ...
                double(numericData(:, 1));
            
            referenceIntensity = ...
                double(numericData(:, 2));
            
            usableRows = ...
                isfinite(referenceWN) & ...
                isfinite(referenceIntensity);
            
            referenceWN = ...
                referenceWN(usableRows);
            
            referenceIntensity = ...
                referenceIntensity(usableRows);
            
            if numel(referenceWN) < 3
                
                error( ...
                    'Reference file contains fewer than 3 usable spectral points.');
            end
            
            % Sort by wavenumber.
            [referenceWN, sortingOrder] = ...
                sort(referenceWN, 'ascend');
            
            referenceIntensity = ...
                referenceIntensity(sortingOrder);
            
            % Merge duplicated wavenumber values using the mean intensity.
            [uniqueWN, ~, groupIndex] = ...
                unique(referenceWN, 'stable');
            
            if numel(uniqueWN) < numel(referenceWN)
                
                mergedIntensity = ...
                    accumarray( ...
                    groupIndex, ...
                    referenceIntensity, ...
                    [], ...
                    @mean);
                
                referenceWN = ...
                    uniqueWN;
                
                referenceIntensity = ...
                    mergedIntensity;
            end
            
            referenceWN = ...
                referenceWN(:);
            
            referenceIntensity = ...
                referenceIntensity(:);
            
        end
        
        
        function referenceData = ...
                loadReferenceSpectraFolder( ...
                app, ...
                referenceFolder)
            
            referenceFolder = ...
                char(string(referenceFolder));
            
            if isempty(referenceFolder) || ...
                    ~isfolder(referenceFolder)
                
                error( ...
                    'The selected reference folder does not exist.');
            end
            
            txtFiles = ...
                dir( ...
                fullfile( ...
                referenceFolder, ...
                '*.txt'));
            
            if isempty(txtFiles)
                
                error( ...
                    'No TXT reference file was found in the selected folder.');
            end
            
            targetWN = ...
                double(app.Wavenumber(:));
            
            interpolatedSpectra = [];
            acceptedFileNames = {};
            rejectedFileNames = {};
            rejectionReasons = {};
            
            for fileNumber = 1:numel(txtFiles)
                
                currentPath = ...
                    fullfile( ...
                    referenceFolder, ...
                    txtFiles(fileNumber).name);
                
                try
                    
                    [referenceWN, referenceIntensity] = ...
                        app.readReferenceSpectrumTXT( ...
                        currentPath);
                    
                    interpolatedIntensity = ...
                        interp1( ...
                        referenceWN, ...
                        referenceIntensity, ...
                        targetWN, ...
                        'linear', ...
                        NaN);
                    
                    usablePointCount = ...
                        nnz(isfinite(interpolatedIntensity));
                    
                    if usablePointCount < ...
                            app.ComponentAssessmentMinimumPoints
                        
                        error( ...
                            sprintf( ...
                            ['Only %d points overlap with the Raman cube axis. ' ...
                            'At least %d are required.'], ...
                            usablePointCount, ...
                            app.ComponentAssessmentMinimumPoints));
                    end
                    
                    interpolatedSpectra(end + 1, :) = ...
                        interpolatedIntensity(:).'; %#ok<AGROW>
                    
                    acceptedFileNames{end + 1, 1} = ...
                        txtFiles(fileNumber).name; %#ok<AGROW>
                    
                catch ME
                    
                    rejectedFileNames{end + 1, 1} = ...
                        txtFiles(fileNumber).name; %#ok<AGROW>
                    
                    rejectionReasons{end + 1, 1} = ...
                        ME.message; %#ok<AGROW>
                end
            end
            
            if isempty(interpolatedSpectra)
                
                error( ...
                    ['None of the TXT files could be used as reference spectra.' ...
                    newline ...
                    'Check the two-column format and wavenumber overlap.']);
            end
            
            % Use only channels available in every accepted reference.
            commonSpectralMask = ...
                all( ...
                isfinite(interpolatedSpectra), ...
                1);
            
            if nnz(commonSpectralMask) < ...
                    app.ComponentAssessmentMinimumPoints
                
                error( ...
                    sprintf( ...
                    ['Only %d common spectral points remain across all ' ...
                    'accepted reference files. At least %d are required.'], ...
                    nnz(commonSpectralMask), ...
                    app.ComponentAssessmentMinimumPoints));
            end
            
            commonWN = ...
                targetWN(commonSpectralMask);
            
            commonReferenceSpectra = ...
                interpolatedSpectra(:, commonSpectralMask);
            
            % =============================================================
            % 750 / 785 RATIO FOR EVERY ACCEPTED REFERENCE
            %
            % Use the unnormalized reference spectra because ratio extraction
            % performs its own local baseline correction.
            % =============================================================
            
            referenceRatioResult = ...
                app.calculateLocalPeakRatio750over785( ...
                commonReferenceSpectra, ...
                commonWN);
            
            referenceRatios = ...
                referenceRatioResult.Ratio;
            
            validReferenceRatios = ...
                referenceRatios( ...
                isfinite(referenceRatios) & ...
                referenceRatios > 0);
            
            if isempty(validReferenceRatios)
                
                referenceRatioMedian = ...
                    NaN;
                
                referenceRatioP10 = ...
                    NaN;
                
                referenceRatioP90 = ...
                    NaN;
                
            else
                
                referenceRatioMedian = ...
                    median( ...
                    validReferenceRatios, ...
                    'omitnan');
                
                referenceRatioP10 = ...
                    prctile( ...
                    validReferenceRatios, ...
                    10);
                
                referenceRatioP90 = ...
                    prctile( ...
                    validReferenceRatios, ...
                    90);
            end
            normalizedReferenceSpectra = ...
                app.normalizeSpectraForComponentAssessment( ...
                commonReferenceSpectra);
            
            consensusReference = ...
                median( ...
                normalizedReferenceSpectra, ...
                1, ...
                'omitnan');
            
            consensusReference = ...
                app.normalizeSpectraForComponentAssessment( ...
                consensusReference);
            
            referenceVariability = ...
                median( ...
                abs( ...
                normalizedReferenceSpectra - ...
                consensusReference), ...
                1, ...
                'omitnan');
            
            % -------------------------------------------------------------
            % Pairwise reference consistency
            % -------------------------------------------------------------
            
            numberReferences = ...
                size(normalizedReferenceSpectra, 1);
            
            pairwiseSimilarity = [];
            
            if numberReferences >= 2
                
                similarityMatrix = ...
                    normalizedReferenceSpectra * ...
                    normalizedReferenceSpectra.';
                
                similarityMatrix = ...
                    max(-1, min(1, similarityMatrix));
                
                upperMask = ...
                    triu( ...
                    true(numberReferences), ...
                    1);
                
                pairwiseSimilarity = ...
                    similarityMatrix(upperMask);
            end
            
            if isempty(pairwiseSimilarity)
                
                consistencyMedian = ...
                    NaN;
                
                consistencyMinimum = ...
                    NaN;
                
            else
                
                consistencyMedian = ...
                    median( ...
                    pairwiseSimilarity, ...
                    'omitnan');
                
                consistencyMinimum = ...
                    min( ...
                    pairwiseSimilarity, ...
                    [], ...
                    'omitnan');
            end
            
            referenceData = struct();
            
            referenceData.Folder = ...
                referenceFolder;
            
            referenceData.AcceptedFiles = ...
                acceptedFileNames;
            
            referenceData.RejectedFiles = ...
                rejectedFileNames;
            
            referenceData.RejectionReasons = ...
                rejectionReasons;
            
            referenceData.Wavenumber = ...
                commonWN(:);
            
            referenceData.CommonSpectralMask = ...
                commonSpectralMask;
            
            referenceData.ReferenceSpectra = ...
                normalizedReferenceSpectra;
            
            referenceData.ConsensusReference = ...
                consensusReference(:).';
            
            referenceData.ReferenceVariability = ...
                referenceVariability(:).';
            
            referenceData.ReferenceConsistencyMedian = ...
                consistencyMedian;
            
            referenceData.ReferenceConsistencyMinimum = ...
                consistencyMinimum;
            referenceData.RawCommonReferenceSpectra = ...
                commonReferenceSpectra;
            
            referenceData.ReferenceRatios750over785 = ...
                referenceRatios;
            
            referenceData.ReferenceRatioMedian = ...
                referenceRatioMedian;
            
            referenceData.ReferenceRatioP10 = ...
                referenceRatioP10;
            
            referenceData.ReferenceRatioP90 = ...
                referenceRatioP90;
            
            referenceData.ReferenceRatioValidCount = ...
                numel(validReferenceRatios);
            
        end
        
        
        function assessmentIndex = ...
                findComponentAssessmentIndex( ...
                app, ...
                clusterID, ...
                componentName)
            
            assessmentIndex = [];
            
            if isempty(app.ComponentAssessments)
                return
            end
            
            clusterMatches = ...
                [app.ComponentAssessments.ClusterID] == ...
                clusterID;
            
            componentMatches = ...
                strcmpi( ...
                {app.ComponentAssessments.ComponentName}, ...
                char(string(componentName)));
            
            assessmentIndex = ...
                find( ...
                clusterMatches & ...
                componentMatches, ...
                1, ...
                'first');
            
        end


        function [representativeCount, decisionInfo] = ...
                determineBalancedRepresentativeCount( ...
                app, ...
                usablePixelCounts)
            
            usablePixelCounts = ...
                double(usablePixelCounts(:));
            
            if isempty(usablePixelCounts) || ...
                    any(~isfinite(usablePixelCounts)) || ...
                    any(usablePixelCounts < 1)
                
                error( ...
                    'One or more selected clusters contain no usable pixels.');
            end
            
            minimumClusterPixels = ...
                min(usablePixelCounts);
            
            preferredRequirement = ...
                app.RepresentativeTargetCount * ...
                app.RepresentativePreferredPixelsPerMean;
            
            fallbackRequirement = ...
                app.RepresentativeFallbackCount * ...
                app.RepresentativePreferredPixelsPerMean;
            
            absoluteFallbackRequirement = ...
                app.RepresentativeFallbackCount * ...
                app.RepresentativeMinimumPixelsPerMean;
            
            decisionInfo = struct();
            
            decisionInfo.MinimumClusterPixels = ...
                minimumClusterPixels;
            
            decisionInfo.PreferredRequirement = ...
                preferredRequirement;
            
            decisionInfo.FallbackRequirement = ...
                fallbackRequirement;
            
            decisionInfo.AbsoluteFallbackRequirement = ...
                absoluteFallbackRequirement;
            
            decisionInfo.RequiresLowPixelWarning = ...
                false;
            
            decisionInfo.Reason = ...
                '';
            
            if minimumClusterPixels >= preferredRequirement
                
                representativeCount = ...
                    app.RepresentativeTargetCount;
                
                decisionInfo.Reason = ...
                    sprintf( ...
                    ['The smallest selected cluster contains %d usable pixels, ' ...
                    'which supports %d representatives with approximately ' ...
                    '%d or more pixels per mean.'], ...
                    minimumClusterPixels, ...
                    representativeCount, ...
                    app.RepresentativePreferredPixelsPerMean);
                
            elseif minimumClusterPixels >= fallbackRequirement
                
                representativeCount = ...
                    app.RepresentativeFallbackCount;
                
                decisionInfo.Reason = ...
                    sprintf( ...
                    ['The smallest selected cluster contains %d usable pixels. ' ...
                    'The balanced output was reduced from %d to %d ' ...
                    'representatives per cluster.'], ...
                    minimumClusterPixels, ...
                    app.RepresentativeTargetCount, ...
                    representativeCount);
                
            elseif minimumClusterPixels >= absoluteFallbackRequirement
                
                representativeCount = ...
                    app.RepresentativeFallbackCount;
                
                decisionInfo.RequiresLowPixelWarning = ...
                    true;
                
                decisionInfo.Reason = ...
                    sprintf( ...
                    ['The smallest selected cluster contains only %d usable pixels. ' ...
                    'The app can still create %d balanced representatives, ' ...
                    'but some means may contain fewer than %d pixels.'], ...
                    minimumClusterPixels, ...
                    representativeCount, ...
                    app.RepresentativePreferredPixelsPerMean);
                
            else
                
                representativeCount = ...
                    app.RepresentativeFallbackCount;
                
                decisionInfo.RequiresLowPixelWarning = ...
                    true;
                
                decisionInfo.Reason = ...
                    sprintf( ...
                    ['The smallest selected cluster contains only %d usable pixels. ' ...
                    'Creating %d representatives will produce some groups ' ...
                    'with only one source pixel.'], ...
                    minimumClusterPixels, ...
                    representativeCount);
            end
            
        end


        function handoffData = ...
                buildBalancedRepresentativeDataset( ...
                app, ...
                selectedClusterIndices, ...
                representativeCount)
            
            selectedClusterIndices = ...
                double(selectedClusterIndices(:));
            
            numberClusters = ...
                numel(selectedClusterIndices);
            
            if numberClusters < 2 || ...
                    numberClusters > 3
                
                error( ...
                    'Representative handoff requires exactly 2 or 3 clusters.');
            end
            
            allSpectra = [];
            allNames = strings(0, 1);
            
            allGroupIDs = strings(0, 1);
            allGroupLabels = strings(0, 1);
            
            allClusterIDs = [];
            allRepresentativeIDs = [];
            allPixelCounts = [];
            allCentroids = [];
            
            allSourcePixels = {};
            
            % The first cluster dataset defines the structure schema.
            clusterDatasets = ...
                struct([]);
            
            for groupNumber = 1:numberClusters
                
                clusterIndex = ...
                    selectedClusterIndices(groupNumber);
                
                clusterDataset = ...
                    app.buildRepresentativeDatasetForCluster( ...
                    clusterIndex, ...
                    groupNumber, ...
                    representativeCount);
                
                if isempty(clusterDatasets)
                    
                    clusterDatasets = ...
                        clusterDataset;
                    
                else
                    
                    clusterDataset = ...
                        orderfields( ...
                        clusterDataset, ...
                        clusterDatasets(1));
                    
                    clusterDatasets(end + 1, 1) = ...
                        clusterDataset;
                end
                
                allSpectra = ...
                    [ ...
                    allSpectra, ...
                    clusterDataset.Spectra]; %#ok<AGROW>
                
                allNames = ...
                    [ ...
                    allNames; ...
                    clusterDataset.Names(:)]; %#ok<AGROW>
                
                allGroupIDs = ...
                    [ ...
                    allGroupIDs; ...
                    repmat( ...
                    clusterDataset.GroupID, ...
                    representativeCount, ...
                    1)]; %#ok<AGROW>
                
                allGroupLabels = ...
                    [ ...
                    allGroupLabels; ...
                    repmat( ...
                    clusterDataset.GroupLabel, ...
                    representativeCount, ...
                    1)]; %#ok<AGROW>
                
                allClusterIDs = ...
                    [ ...
                    allClusterIDs; ...
                    repmat( ...
                    clusterDataset.ClusterID, ...
                    representativeCount, ...
                    1)]; %#ok<AGROW>
                
                allRepresentativeIDs = ...
                    [ ...
                    allRepresentativeIDs; ...
                    (1:representativeCount)']; %#ok<AGROW>
                
                allPixelCounts = ...
                    [ ...
                    allPixelCounts; ...
                    clusterDataset.PixelCounts(:)]; %#ok<AGROW>
                
                allCentroids = ...
                    [ ...
                    allCentroids; ...
                    clusterDataset.SpatialCentroids]; %#ok<AGROW>
                
                allSourcePixels = ...
                    [ ...
                    allSourcePixels; ...
                    clusterDataset.SourcePixelIndices(:)]; %#ok<AGROW>
            end
            
            totalSpectra = ...
                size(allSpectra, 2);
            
            if totalSpectra ~= ...
                    representativeCount * numberClusters
                
                error( ...
                    'The balanced representative dataset has an unexpected size.');
            end
            
            handoffData = struct();
            
            handoffData.Mode = ...
                1;
            
            handoffData.Wavenumber = ...
                double(app.Wavenumber(:));
            
            handoffData.Spectra = ...
                allSpectra;
            
            handoffData.RawSpectra = ...
                allSpectra;
            
            handoffData.Names = ...
                allNames;
            
            handoffData.GroupIDs = ...
                allGroupIDs;
            
            handoffData.GroupLabels = ...
                allGroupLabels;
            
            handoffData.ClusterIDs = ...
                allClusterIDs;
            
            handoffData.RepresentativeIDs = ...
                allRepresentativeIDs;
            
            handoffData.PixelCounts = ...
                allPixelCounts;
            
            handoffData.SpatialCentroids = ...
                allCentroids;
            
            handoffData.SourcePixelIndices = ...
                allSourcePixels;
            
            handoffData.ClusterDatasets = ...
                clusterDatasets;
            
            handoffData.RepresentativesPerCluster = ...
                representativeCount;
            
            handoffData.NumberClusters = ...
                numberClusters;
            
            handoffData.TotalSpectra = ...
                totalSpectra;
            
            handoffData.ScanName = ...
                app.CurrentScanName;
            
            handoffData.ScanID = ...
                app.CurrentScanID;
            
            handoffData.CreatedAt = ...
                datetime('now');
            
            handoffData.GenerationMethod = ...
                ['Balanced recursive spatial partitioning; ' ...
                'one mean spectrum per spatial pixel group'];
            
        end
        
        
        function spectralDef = ...
                getSpectralDefinitionForSingleMode(app)
            
            spectralDef = ...
                struct();
            
            if isempty(app.RamanData) || ...
                    ~isstruct(app.RamanData)
                
                return
            end
            
            candidateFields = { ...
                'SpectralDefinition', ...
                'SpectralDef', ...
                'spectralDef', ...
                'DefineSpectralResult'};
            
            for fieldNumber = 1:numel(candidateFields)
                
                currentField = ...
                    candidateFields{fieldNumber};
                
                if isfield(app.RamanData, currentField) && ...
                        isstruct(app.RamanData.(currentField))
                    
                    spectralDef = ...
                        app.RamanData.(currentField);
                    
                    return
                end
            end
            
        end
        
        
        function assessmentIndex = ...
                getCurrentDisplayComponentAssessmentIndex(app)
            
            % =============================================================
            % RESOLVE COMPONENT ASSESSMENT FOR DISPLAY
            %
            % Priority:
            %   1. Component-assessment node selected directly in Tree.
            %   2. Current stored assessment index.
            %   3. Latest assessment of the selected cluster.
            % =============================================================
            
            assessmentIndex = [];
            
            if isempty(app.ComponentAssessments)
                return
            end
            
            % -------------------------------------------------------------
            % 1. Directly selected component node
            % -------------------------------------------------------------
            
            directIndex = ...
                app.getSelectedComponentAssessmentIndexFromTree();
            
            if ~isempty(directIndex) && ...
                    isfinite(directIndex) && ...
                    directIndex >= 1 && ...
                    directIndex <= numel(app.ComponentAssessments)
                
                assessmentIndex = ...
                    directIndex;
                
                return
            end
            
            % -------------------------------------------------------------
            % 2. Current component assessment
            % -------------------------------------------------------------
            
            if isfinite(app.CurrentComponentAssessmentIndex) && ...
                    app.CurrentComponentAssessmentIndex >= 1 && ...
                    app.CurrentComponentAssessmentIndex <= ...
                    numel(app.ComponentAssessments)
                
                assessmentIndex = ...
                    app.CurrentComponentAssessmentIndex;
                
                return
            end
            
            % -------------------------------------------------------------
            % 3. Latest assessment of one selected cluster
            % -------------------------------------------------------------
            
            selectedIDs = ...
                app.SelectedClusterIDs;
            
            if numel(selectedIDs) == 1
                
                latestIndex = ...
                    app.findLatestComponentAssessmentForCluster( ...
                    selectedIDs(1));
                
                if ~isempty(latestIndex)
                    
                    assessmentIndex = ...
                        latestIndex;
                end
            end
            
        end


        function usablePixelIdx = ...
                getUsableRepresentativePixels( ...
                app, ...
                clusterIndex)
            
            usablePixelIdx = [];
            
            if isempty(clusterIndex) || ...
                    clusterIndex < 1 || ...
                    clusterIndex > numel(app.Clusters)
                
                return
            end
            
            C = ...
                app.Clusters(clusterIndex);
            
            if ~C.Active || ...
                    isempty(C.PixelIdx)
                
                return
            end
            
            candidatePixelIdx = ...
                unique( ...
                double(C.PixelIdx(:)), ...
                'stable');
            
            [ny, nx, numberWavenumbers] = ...
                size(app.RamanCube);
            
            maximumPixelIndex = ...
                ny * nx;
            
            insideCube = ...
                candidatePixelIdx >= 1 & ...
                candidatePixelIdx <= maximumPixelIndex;
            
            candidatePixelIdx = ...
                candidatePixelIdx(insideCube);
            
            if isempty(candidatePixelIdx)
                return
            end
            
            spatialValid = ...
                app.ValidMask2D(candidatePixelIdx);
            
            if ~isempty(app.ArtifactMask2D) && ...
                    isequal( ...
                    size(app.ArtifactMask2D), ...
                    [ny nx])
                
                artifactFree = ...
                    ~app.ArtifactMask2D(candidatePixelIdx);
                
            else
                
                artifactFree = ...
                    true(size(candidatePixelIdx));
            end
            
            allSpectra = ...
                reshape( ...
                double(app.RamanCube), ...
                ny * nx, ...
                numberWavenumbers);
            
            candidateSpectra = ...
                allSpectra(candidatePixelIdx, :);
            
            finiteSpectra = ...
                all( ...
                isfinite(candidateSpectra), ...
                2);
            
            keepPixel = ...
                spatialValid & ...
                artifactFree & ...
                finiteSpectra;
            
            usablePixelIdx = ...
                candidatePixelIdx(keepPixel);
            
            usablePixelIdx = ...
                usablePixelIdx(:);
            
        end


        function spatialGroups = ...
                partitionPixelsIntoSpatialGroups( ...
                app, ...
                pixelIdx, ...
                numberGroups)
            
            %#ok<INUSD>
            
            pixelIdx = ...
                unique( ...
                double(pixelIdx(:)), ...
                'stable');
            
            numberGroups = ...
                max(1, round(numberGroups));
            
            if isempty(pixelIdx)
                
                error( ...
                    'No pixel is available for spatial partitioning.');
            end
            
            if numel(pixelIdx) < numberGroups
                
                error( ...
                    sprintf( ...
                    ['Only %d pixels are available, but %d representative ' ...
                    'groups were requested.'], ...
                    numel(pixelIdx), ...
                    numberGroups));
            end
            
            [numberRows, numberColumns] = ...
                size(app.ValidMask2D);
            
            [pixelRows, pixelColumns] = ...
                ind2sub( ...
                [numberRows numberColumns], ...
                pixelIdx);
            
            coordinateTable = ...
                [ ...
                double(pixelRows(:)), ...
                double(pixelColumns(:)), ...
                pixelIdx(:)];
            
            spatialGroups = ...
                {coordinateTable};
            
            while numel(spatialGroups) < numberGroups
                
                splittableGroupIndices = [];
                
                splitPriorities = [];
                
                for groupNumber = 1:numel(spatialGroups)
                    
                    currentGroup = ...
                        spatialGroups{groupNumber};
                    
                    if size(currentGroup, 1) < 2
                        continue
                    end
                    
                    rowSpread = ...
                        max(currentGroup(:, 1)) - ...
                        min(currentGroup(:, 1));
                    
                    columnSpread = ...
                        max(currentGroup(:, 2)) - ...
                        min(currentGroup(:, 2));
                    
                    spatialSpread = ...
                        max(rowSpread, columnSpread);
                    
                    % First priority: group population.
                    % Second priority: spatial spread.
                    priorityValue = ...
                        1e6 * size(currentGroup, 1) + ...
                        spatialSpread;
                    
                    splittableGroupIndices(end + 1, 1) = ...
                        groupNumber; %#ok<AGROW>
                    
                    splitPriorities(end + 1, 1) = ...
                        priorityValue; %#ok<AGROW>
                end
                
                if isempty(splittableGroupIndices)
                    
                    error( ...
                        ['The selected pixels could not be divided into the ' ...
                        'requested number of non-empty spatial groups.']);
                end
                
                [~, priorityPosition] = ...
                    max(splitPriorities);
                
                groupToSplitIndex = ...
                    splittableGroupIndices(priorityPosition);
                
                groupToSplit = ...
                    spatialGroups{groupToSplitIndex};
                
                rowSpread = ...
                    max(groupToSplit(:, 1)) - ...
                    min(groupToSplit(:, 1));
                
                columnSpread = ...
                    max(groupToSplit(:, 2)) - ...
                    min(groupToSplit(:, 2));
                
                if columnSpread > rowSpread
                    
                    primaryCoordinate = ...
                        2;
                    
                    secondaryCoordinate = ...
                        1;
                    
                else
                    
                    primaryCoordinate = ...
                        1;
                    
                    secondaryCoordinate = ...
                        2;
                end
                
                groupToSplit = ...
                    sortrows( ...
                    groupToSplit, ...
                    [primaryCoordinate secondaryCoordinate]);
                
                splitPosition = ...
                    floor(size(groupToSplit, 1) / 2);
                
                splitPosition = ...
                    max( ...
                    1, ...
                    min( ...
                    size(groupToSplit, 1) - 1, ...
                    splitPosition));
                
                firstGroup = ...
                    groupToSplit(1:splitPosition, :);
                
                secondGroup = ...
                    groupToSplit(splitPosition + 1:end, :);
                
                spatialGroups{groupToSplitIndex} = ...
                    firstGroup;
                
                spatialGroups{end + 1} = ...
                    secondGroup; %#ok<AGROW>
            end
            
            % =============================================================
            % FINAL ORDER
            %
            % Sort groups by spatial centroid so representative numbering
            % remains reproducible and understandable.
            % =============================================================
            
            groupCentroids = ...
                zeros(numel(spatialGroups), 2);
            
            for groupNumber = 1:numel(spatialGroups)
                
                groupCentroids(groupNumber, :) = ...
                    mean( ...
                    spatialGroups{groupNumber}(:, 1:2), ...
                    1, ...
                    'omitnan');
            end
            
            [~, sortingOrder] = ...
                sortrows( ...
                groupCentroids, ...
                [1 2]);
            
            spatialGroups = ...
                spatialGroups(sortingOrder);
            
        end

        function clusterDataset = ...
                buildRepresentativeDatasetForCluster( ...
                app, ...
                clusterIndex, ...
                groupNumber, ...
                representativeCount)
            
            C = ...
                app.Clusters(clusterIndex);
            
            usablePixelIdx = ...
                app.getUsableRepresentativePixels( ...
                clusterIndex);
            
            if numel(usablePixelIdx) < representativeCount
                
                error( ...
                    sprintf( ...
                    ['%s has only %d usable pixels and cannot produce ' ...
                    '%d non-empty representative spectra.'], ...
                    C.Name, ...
                    numel(usablePixelIdx), ...
                    representativeCount));
            end
            
            spatialGroups = ...
                app.partitionPixelsIntoSpatialGroups( ...
                usablePixelIdx, ...
                representativeCount);
            
            [ny, nx, numberWavenumbers] = ...
                size(app.RamanCube);
            
            allSpectra = ...
                reshape( ...
                double(app.RamanCube), ...
                ny * nx, ...
                numberWavenumbers);
            
            representativeSpectra = ...
                nan( ...
                numberWavenumbers, ...
                representativeCount);
            
            representativeSD = ...
                nan( ...
                numberWavenumbers, ...
                representativeCount);
            
            representativeNames = ...
                strings( ...
                representativeCount, ...
                1);
            
            representativePixelCounts = ...
                zeros( ...
                representativeCount, ...
                1);
            
            representativeCentroids = ...
                nan( ...
                representativeCount, ...
                2);
            
            representativePixelIndices = ...
                cell( ...
                representativeCount, ...
                1);
            
            if ~isempty(C.Class) && ...
                    ~strcmpi(C.Class, 'Unassigned')
                
                groupLabel = ...
                    sprintf( ...
                    '%s_%s', ...
                    C.Class, ...
                    C.Name);
                
            else
                
                groupLabel = ...
                    C.Name;
            end
            
            groupLabel = ...
                app.makeRepresentativeGroupName( ...
                groupLabel);
            
            groupID = ...
                sprintf( ...
                'G%02d', ...
                groupNumber);
            
            for representativeNumber = 1:representativeCount
                
                currentGroupTable = ...
                    spatialGroups{representativeNumber};
                
                currentPixelIdx = ...
                    double(currentGroupTable(:, 3));
                
                currentSpectra = ...
                    allSpectra(currentPixelIdx, :);
                
                validSpectrumRows = ...
                    all( ...
                    isfinite(currentSpectra), ...
                    2);
                
                currentPixelIdx = ...
                    currentPixelIdx(validSpectrumRows);
                
                currentSpectra = ...
                    currentSpectra(validSpectrumRows, :);
                
                if isempty(currentSpectra)
                    
                    error( ...
                        sprintf( ...
                        ['Representative %d of %s contains no finite ' ...
                        'source spectrum.'], ...
                        representativeNumber, ...
                        C.Name));
                end
                
                representativeSpectra(:, representativeNumber) = ...
                    mean( ...
                    currentSpectra, ...
                    1, ...
                    'omitnan').';
                
                representativeSD(:, representativeNumber) = ...
                    std( ...
                    currentSpectra, ...
                    0, ...
                    1, ...
                    'omitnan').';
                
                representativePixelCounts(representativeNumber) = ...
                    numel(currentPixelIdx);
                
                [currentRows, currentColumns] = ...
                    ind2sub( ...
                    [ny nx], ...
                    currentPixelIdx);
                
                representativeCentroids(representativeNumber, :) = ...
                    [ ...
                    mean(currentRows, 'omitnan'), ...
                    mean(currentColumns, 'omitnan')];
                
                representativePixelIndices{representativeNumber} = ...
                    currentPixelIdx(:);
                
                representativeNames(representativeNumber) = ...
                    sprintf( ...
                    '%s_%s_Spec%03d', ...
                    groupID, ...
                    groupLabel, ...
                    representativeNumber);
            end
            
            clusterDataset = struct();
            
            clusterDataset.ClusterID = ...
                C.ID;
            
            clusterDataset.ClusterName = ...
                C.Name;
            
            clusterDataset.ClusterClass = ...
                C.Class;
            
            clusterDataset.GroupID = ...
                string(groupID);
            
            clusterDataset.GroupLabel = ...
                string(groupLabel);
            
            clusterDataset.Spectra = ...
                representativeSpectra;
            
            clusterDataset.SD = ...
                representativeSD;
            
            clusterDataset.Names = ...
                representativeNames;
            
            clusterDataset.PixelCounts = ...
                representativePixelCounts;
            
            clusterDataset.SpatialCentroids = ...
                representativeCentroids;
            
            clusterDataset.SourcePixelIndices = ...
                representativePixelIndices;
            
            clusterDataset.UsablePixelCount = ...
                numel(usablePixelIdx);
            
            clusterDataset.RepresentativeCount = ...
                representativeCount;
            
        end
        
        
        function reviewMeta = ...
                buildSingleModeReviewMetadata( ...
                app, ...
                handoffData)
            
            %#ok<INUSD>
            
            numberSpectra = ...
                handoffData.TotalSpectra;
            
            spectrumNames = ...
                string(handoffData.Names(:));
            
            groupIDs = ...
                string(handoffData.GroupIDs(:));
            
            groupLabels = ...
                string(handoffData.GroupLabels(:));
            
            filePaths = ...
                repmat( ...
                "", ...
                numberSpectra, ...
                1);
            
            sourceIndex = ...
                (1:numberSpectra)';
            
            reviewMeta = ...
                table( ...
                spectrumNames, ...
                groupIDs, ...
                groupLabels, ...
                filePaths, ...
                sourceIndex, ...
                'VariableNames', ...
                { ...
                'SpectrumName', ...
                'GroupID', ...
                'GroupLabel', ...
                'FilePath', ...
                'SourceIndex'});
            
        end


        function displayComponentAssessmentThreeLevelOnAxis4( ...
                app, ...
                assessmentIndex)
            
            % =============================================================
            % THREE-LEVEL 750/785 RATIO-COMPATIBILITY MAP
            %
            % Level 1 = Weak
            %   score < 0.60
            %
            % Level 2 = Medium
            %   0.60 <= score < 0.80
            %
            % Level 3 = Strong
            %   score >= 0.80
            %
            % Invalid ratio pixels remain transparent.
            % =============================================================
            
            if nargin < 2 || ...
                    isempty(assessmentIndex) || ...
                    ~isfinite(assessmentIndex) || ...
                    assessmentIndex < 1 || ...
                    assessmentIndex > numel(app.ComponentAssessments)
                
                return
            end
            
            assessment = ...
                app.ComponentAssessments(assessmentIndex);
            
            assessment = ...
                app.updateComponentAssessmentStaleState( ...
                assessment);
            
            app.ComponentAssessments(assessmentIndex) = ...
                assessment;
            
            app.CurrentComponentAssessmentIndex = ...
                assessmentIndex;
            
            app.resetImageAxis(app.UIAxes_4);
            
            % =============================================================
            % 1. VALIDATE RATIO MAP
            % =============================================================
            
            if ~isfield(assessment, 'RatioScoreMap') || ...
                    isempty(assessment.RatioScoreMap)
                
                text( ...
                    app.UIAxes_4, ...
                    0.5, ...
                    0.5, ...
                    'No 750/785 ratio score map is available', ...
                    'Units', ...
                    'normalized', ...
                    'HorizontalAlignment', ...
                    'center');
                
                title( ...
                    app.UIAxes_4, ...
                    'Three-level reference-ratio map');
                
                return
            end
            
            ratioScoreMap = ...
                double(assessment.RatioScoreMap);
            
            validRatioMask = ...
                isfinite(ratioScoreMap);
            
            if ~any(validRatioMask(:))
                
                text( ...
                    app.UIAxes_4, ...
                    0.5, ...
                    0.5, ...
                    { ...
                    'No valid 750/785 ratio score is available.'; ...
                    'The 785 denominator may be below the reliability floor.'}, ...
                    'Units', ...
                    'normalized', ...
                    'HorizontalAlignment', ...
                    'center');
                
                title( ...
                    app.UIAxes_4, ...
                    sprintf( ...
                    '%s - %s ratio levels', ...
                    assessment.ClusterName, ...
                    assessment.ComponentName), ...
                    'Interpreter', ...
                    'none');
                
                return
            end
            
            % =============================================================
            % 2. BUILD THREE-LEVEL CLASS MAP
            %
            % NaN = invalid or outside evaluated region
            % 1   = Weak
            % 2   = Medium
            % 3   = Strong
            % =============================================================
            
            levelMap = ...
                nan(size(ratioScoreMap));
            
            weakMask = ...
                validRatioMask & ...
                ratioScoreMap < ...
                app.RatioScoreModerateThreshold;
            
            mediumMask = ...
                validRatioMask & ...
                ratioScoreMap >= ...
                app.RatioScoreModerateThreshold & ...
                ratioScoreMap < ...
                app.RatioScoreStrongThreshold;
            
            strongMask = ...
                validRatioMask & ...
                ratioScoreMap >= ...
                app.RatioScoreStrongThreshold;
            
            levelMap(weakMask) = ...
                1;
            
            levelMap(mediumMask) = ...
                2;
            
            levelMap(strongMask) = ...
                3;
            
            % =============================================================
            % 3. DISPLAY DISCRETE MAP
            % =============================================================
            
            imageHandle = ...
                imagesc( ...
                app.UIAxes_4, ...
                levelMap, ...
                [0.5 3.5]);
            
            imageHandle.AlphaData = ...
                double(isfinite(levelMap));
            
            imageHandle.AlphaDataMapping = ...
                'none';
            
            imageHandle.HitTest = ...
                'off';
            
            axis(app.UIAxes_4, 'image');
            
            app.UIAxes_4.YDir = ...
                'normal';
            
            app.UIAxes_4.Color = ...
                [0 0 0];
            
            % Weak, Medium, Strong.
            %
            % Three clearly separated colors are used because this is a
            % categorical evidence display, not a continuous quantitative map.
            levelColors = [ ...
                0.20 0.38 0.78; ...
                0.96 0.72 0.12; ...
                0.88 0.16 0.16];
            
            colormap( ...
                app.UIAxes_4, ...
                levelColors);
            
            colorbarHandle = ...
                colorbar(app.UIAxes_4);
            
            colorbarHandle.Ticks = ...
                [1 2 3];
            
            colorbarHandle.TickLabels = ...
                { ...
                'Weak', ...
                'Medium', ...
                'Strong'};
            
            colorbarHandle.Label.String = ...
                '750/785 reference-ratio support level';
            
            % =============================================================
            % 4. CLUSTER BOUNDARY
            % =============================================================
            
            clusterMask = ...
                false(size(ratioScoreMap));
            
            clusterIndex = ...
                app.findClusterIndexByID( ...
                assessment.ClusterID);
            
            if ~isempty(clusterIndex)
                
                clusterMask( ...
                    app.Clusters(clusterIndex).PixelIdx) = ...
                    true;
                
            elseif ~isempty(assessment.PixelIdx)
                
                clusterMask(assessment.PixelIdx) = ...
                    true;
            end
            
            hold(app.UIAxes_4, 'on');
            
            if any(clusterMask(:))
                
                contour( ...
                    app.UIAxes_4, ...
                    clusterMask, ...
                    [0.5 0.5], ...
                    'w', ...
                    'LineWidth', ...
                    1.7, ...
                    'HitTest', ...
                    'off');
            end
            
            % Boundary between Weak and Medium.
            contour( ...
                app.UIAxes_4, ...
                ratioScoreMap, ...
                [ ...
                app.RatioScoreModerateThreshold, ...
                app.RatioScoreModerateThreshold], ...
                'Color', ...
                [1 1 1], ...
                'LineStyle', ...
                '--', ...
                'LineWidth', ...
                0.9, ...
                'HitTest', ...
                'off');
            
            % Boundary between Medium and Strong.
            contour( ...
                app.UIAxes_4, ...
                ratioScoreMap, ...
                [ ...
                app.RatioScoreStrongThreshold, ...
                app.RatioScoreStrongThreshold], ...
                'Color', ...
                [1 1 1], ...
                'LineStyle', ...
                '-', ...
                'LineWidth', ...
                1.3, ...
                'HitTest', ...
                'off');
            
            hold(app.UIAxes_4, 'off');
            
            % =============================================================
            % 5. COMPOSITION COUNTS
            % =============================================================
            
            validCount = ...
                nnz(validRatioMask);
            
            weakFraction = ...
                nnz(weakMask) / ...
                max(1, validCount);
            
            mediumFraction = ...
                nnz(mediumMask) / ...
                max(1, validCount);
            
            strongFraction = ...
                nnz(strongMask) / ...
                max(1, validCount);
            
            % =============================================================
            % 6. TITLE
            % =============================================================
            
            if assessment.IsStale
                
                statusLine = ...
                    'STALE: cluster changed; rerun before interpretation';
                
            elseif isfinite(assessment.ReferenceRatioMedian)
                
                statusLine = ...
                    sprintf( ...
                    'Reference ratio 750/785 = %.4g', ...
                    assessment.ReferenceRatioMedian);
                
            else
                
                statusLine = ...
                    'Reference ratio unavailable';
            end
            
            title( ...
                app.UIAxes_4, ...
                { ...
                sprintf( ...
                '%s - %s 750/785 support levels', ...
                assessment.ClusterName, ...
                assessment.ComponentName), ...
                statusLine, ...
                sprintf( ...
                'Strong %.1f%% | Medium %.1f%% | Weak %.1f%%', ...
                100 * strongFraction, ...
                100 * mediumFraction, ...
                100 * weakFraction)}, ...
                'Interpreter', ...
                'none');
            
        end


        function assessmentIndex = ...
                storeComponentAssessment( ...
                app, ...
                newAssessment)
            
            % =============================================================
            % STORE OR UPDATE ONE COMPONENT ASSESSMENT
            %
            % Key:
            %   ClusterID + ComponentName
            %
            % Results for other cluster/component pairs are preserved.
            % =============================================================
            
            % -------------------------------------------------------------
            % 1. Validate input
            % -------------------------------------------------------------
            
            if isempty(newAssessment) || ...
                    ~isstruct(newAssessment) || ...
                    numel(newAssessment) ~= 1
                
                error( ...
                    'newAssessment must be one scalar structure.');
            end
            
            requiredKeyFields = { ...
                'ClusterID', ...
                'ComponentName'};
            
            for fieldNumber = 1:numel(requiredKeyFields)
                
                currentField = ...
                    requiredKeyFields{fieldNumber};
                
                if ~isfield(newAssessment, currentField)
                    
                    error( ...
                        'Component assessment is missing field: %s', ...
                        currentField);
                end
            end
            
            % -------------------------------------------------------------
            % 2. Create canonical empty schema
            % -------------------------------------------------------------
            
            canonicalArray = ...
                app.makeEmptyComponentAssessments();
            
            canonicalFields = ...
                fieldnames(canonicalArray);
            
            newFields = ...
                fieldnames(newAssessment);
            
            % -------------------------------------------------------------
            % 3. Add any missing canonical fields to newAssessment
            % -------------------------------------------------------------
            
            for fieldNumber = 1:numel(canonicalFields)
                
                currentField = ...
                    canonicalFields{fieldNumber};
                
                if ~isfield(newAssessment, currentField)
                    
                    newAssessment.(currentField) = [];
                end
            end
            
            % -------------------------------------------------------------
            % 4. Reject unexpected fields
            %
            % Unexpected fields usually indicate that one struct definition
            % was updated but another one was not.
            % -------------------------------------------------------------
            
            unexpectedFields = ...
                setdiff( ...
                newFields, ...
                canonicalFields, ...
                'stable');
            
            if ~isempty(unexpectedFields)
                
                error( ...
                    ['The new component assessment contains unexpected fields:' ...
                    newline ...
                    strjoin(unexpectedFields, ', ')]);
            end
            
            % Reorder fields exactly like the canonical schema.
            newAssessment = ...
                orderfields( ...
                newAssessment, ...
                canonicalArray);
            
            % -------------------------------------------------------------
            % 5. Normalize existing array schema
            % -------------------------------------------------------------
            
            if isempty(app.ComponentAssessments)
                
                app.ComponentAssessments = ...
                    app.makeEmptyComponentAssessments();
                
            else
                
                existingFields = ...
                    fieldnames(app.ComponentAssessments);
                
                missingExistingFields = ...
                    setdiff( ...
                    canonicalFields, ...
                    existingFields, ...
                    'stable');
                
                for fieldNumber = 1:numel(missingExistingFields)
                    
                    currentField = ...
                        missingExistingFields{fieldNumber};
                    
                    [app.ComponentAssessments.(currentField)] = ...
                        deal([]);
                end
                
                unexpectedExistingFields = ...
                    setdiff( ...
                    existingFields, ...
                    canonicalFields, ...
                    'stable');
                
                if ~isempty(unexpectedExistingFields)
                    
                    app.ComponentAssessments = ...
                        rmfield( ...
                        app.ComponentAssessments, ...
                        unexpectedExistingFields);
                end
                
                app.ComponentAssessments = ...
                    orderfields( ...
                    app.ComponentAssessments, ...
                    canonicalArray);
            end
            
            % -------------------------------------------------------------
            % 6. Find same ClusterID + ComponentName
            % -------------------------------------------------------------
            
            existingIndex = ...
                app.findComponentAssessmentIndex( ...
                newAssessment.ClusterID, ...
                newAssessment.ComponentName);
            
            % -------------------------------------------------------------
            % 7. Append or replace only matching record
            % -------------------------------------------------------------
            
            if isempty(existingIndex)
                
                app.ComponentAssessments(end + 1) = ...
                    newAssessment;
                
                assessmentIndex = ...
                    numel(app.ComponentAssessments);
                
            else
                
                app.ComponentAssessments(existingIndex) = ...
                    newAssessment;
                
                assessmentIndex = ...
                    existingIndex;
            end
            
            app.CurrentComponentAssessmentIndex = ...
                assessmentIndex;
            
        end
        
        
        function assessment = ...
                calculateReferenceComponentAssessment( ...
                app, ...
                clusterIndex, ...
                componentName, ...
                referenceData, ...
                lowThreshold, ...
                highThreshold)
            
            C = ...
                app.Clusters(clusterIndex);
            
            if ~C.Active || ...
                    isempty(C.PixelIdx)
                
                error( ...
                    'The selected cluster is inactive or contains no pixels.');
            end
            
            clusterPixelIdx = ...
                double(C.PixelIdx(:));
            
            [ny, nx, numberWavenumbers] = ...
                size(app.RamanCube);
            
            allSpectra = ...
                reshape( ...
                double(app.RamanCube), ...
                ny * nx, ...
                numberWavenumbers);
            
            clusterSpectraFull = ...
                allSpectra(clusterPixelIdx, :);
            
            finiteRowsFull = ...
                all( ...
                isfinite(clusterSpectraFull), ...
                2);
            
            evaluatedPixelIdx = ...
                clusterPixelIdx(finiteRowsFull);
            
            clusterSpectraFull = ...
                clusterSpectraFull(finiteRowsFull, :);
            
            if isempty(clusterSpectraFull)
                
                error( ...
                    'No finite spectrum remains inside the selected cluster.');
            end
            
            % =============================================================
            % 1. WHOLE-SPECTRUM REFERENCE MATCH
            % =============================================================
            
            commonSpectralMask = ...
                logical(referenceData.CommonSpectralMask);
            
            clusterSpectraForMatch = ...
                clusterSpectraFull(:, commonSpectralMask);
            
            normalizedPixelSpectra = ...
                app.normalizeSpectraForComponentAssessment( ...
                clusterSpectraForMatch);
            
            consensusReference = ...
                double(referenceData.ConsensusReference(:));
            
            matchScores = ...
                normalizedPixelSpectra * ...
                consensusReference;
            
            matchScores = ...
                max(-1, min(1, matchScores));
            
            matchScores = ...
                max(0, matchScores);
            
            matchScoreMap = ...
                nan(ny, nx);
            
            matchScoreMap(evaluatedPixelIdx) = ...
                matchScores;
            
            clusterMask = ...
                false(ny, nx);
            
            clusterMask(clusterPixelIdx) = ...
                true;
            
            lowSupportMask = ...
                clusterMask & ...
                isfinite(matchScoreMap) & ...
                matchScoreMap < lowThreshold;
            
            intermediateSupportMask = ...
                clusterMask & ...
                isfinite(matchScoreMap) & ...
                matchScoreMap >= lowThreshold & ...
                matchScoreMap < highThreshold;
            
            highSupportMask = ...
                clusterMask & ...
                isfinite(matchScoreMap) & ...
                matchScoreMap >= highThreshold;
            
            % =============================================================
            % 2. 750 / 785 PIXEL RATIO
            %
            % Ratio is calculated using the original Raman axis because
            % the local peak windows are defined in cm^-1.
            % =============================================================
            
            pixelRatioResult = ...
                app.calculateLocalPeakRatio750over785( ...
                clusterSpectraFull, ...
                app.Wavenumber);
            
            pixelRatios = ...
                pixelRatioResult.Ratio;
            
            referenceRatio = ...
                referenceData.ReferenceRatioMedian;
            
            ratioScoreValues = ...
                app.calculateRatioCompatibilityScore( ...
                pixelRatios, ...
                referenceRatio);
            
            ratioScoreMap = ...
                nan(ny, nx);
            
            ratioScoreMap(evaluatedPixelIdx) = ...
                ratioScoreValues;
            
            ratioValidMask = ...
                false(ny, nx);
            
            validRatioRows = ...
                isfinite(ratioScoreValues);
            
            ratioValidMask( ...
                evaluatedPixelIdx(validRatioRows)) = ...
                true;
            
            % =============================================================
            % 3. FOUR RATIO-SUPPORT LEVELS
            %
            % Level definitions:
            %   < 0.40       = low
            %   0.40-0.60    = weak
            %   0.60-0.80    = moderate
            %   0.80-1.00    = strong
            % =============================================================
            
            ratioLowMask = ...
                clusterMask & ...
                ratioValidMask & ...
                ratioScoreMap < ...
                app.RatioScoreWeakThreshold;
            
            ratioWeakMask = ...
                clusterMask & ...
                ratioValidMask & ...
                ratioScoreMap >= ...
                app.RatioScoreWeakThreshold & ...
                ratioScoreMap < ...
                app.RatioScoreModerateThreshold;
            
            ratioModerateMask = ...
                clusterMask & ...
                ratioValidMask & ...
                ratioScoreMap >= ...
                app.RatioScoreModerateThreshold & ...
                ratioScoreMap < ...
                app.RatioScoreStrongThreshold;
            
            ratioStrongMask = ...
                clusterMask & ...
                ratioValidMask & ...
                ratioScoreMap >= ...
                app.RatioScoreStrongThreshold;
            
            % =============================================================
            % 4. WHOLE-SPECTRUM METRICS
            % =============================================================
            
            numberEvaluated = ...
                numel(matchScores);
            
            metrics = struct();
            
            metrics.ClusterPixelCount = ...
                numel(clusterPixelIdx);
            
            metrics.EvaluatedPixelCount = ...
                numberEvaluated;
            
            metrics.EvaluatedFraction = ...
                numberEvaluated / ...
                max(1, numel(clusterPixelIdx));
            
            metrics.LowSupportCount = ...
                nnz(lowSupportMask);
            
            metrics.IntermediateSupportCount = ...
                nnz(intermediateSupportMask);
            
            metrics.HighSupportCount = ...
                nnz(highSupportMask);
            
            metrics.LowSupportFraction = ...
                metrics.LowSupportCount / ...
                max(1, numberEvaluated);
            
            metrics.IntermediateSupportFraction = ...
                metrics.IntermediateSupportCount / ...
                max(1, numberEvaluated);
            
            metrics.HighSupportFraction = ...
                metrics.HighSupportCount / ...
                max(1, numberEvaluated);
            
            metrics.MeanMatchScore = ...
                mean(matchScores, 'omitnan');
            
            metrics.MedianMatchScore = ...
                median(matchScores, 'omitnan');
            
            metrics.P10MatchScore = ...
                prctile(matchScores, 10);
            
            metrics.P90MatchScore = ...
                prctile(matchScores, 90);
            
            metrics.MinimumMatchScore = ...
                min(matchScores, [], 'omitnan');
            
            metrics.MaximumMatchScore = ...
                max(matchScores, [], 'omitnan');
            
            metrics.EstimatedReferenceSupportedFraction = ...
                metrics.HighSupportFraction;
            
            % =============================================================
            % 5. RATIO METRICS
            % =============================================================
            
            validRatioValues = ...
                pixelRatios(isfinite(pixelRatios));
            
            validRatioScores = ...
                ratioScoreValues(isfinite(ratioScoreValues));
            
            numberRatioValid = ...
                numel(validRatioScores);
            
            metrics.RatioValidPixelCount = ...
                numberRatioValid;
            
            metrics.RatioUnavailablePixelCount = ...
                numel(clusterPixelIdx) - ...
                numberRatioValid;
            
            metrics.RatioValidFraction = ...
                numberRatioValid / ...
                max(1, numel(clusterPixelIdx));
            
            metrics.RatioLowCount = ...
                nnz(ratioLowMask);
            
            metrics.RatioWeakCount = ...
                nnz(ratioWeakMask);
            
            metrics.RatioModerateCount = ...
                nnz(ratioModerateMask);
            
            metrics.RatioStrongCount = ...
                nnz(ratioStrongMask);
            
            metrics.RatioLowFraction = ...
                metrics.RatioLowCount / ...
                max(1, numberRatioValid);
            
            metrics.RatioWeakFraction = ...
                metrics.RatioWeakCount / ...
                max(1, numberRatioValid);
            
            metrics.RatioModerateFraction = ...
                metrics.RatioModerateCount / ...
                max(1, numberRatioValid);
            
            metrics.RatioStrongFraction = ...
                metrics.RatioStrongCount / ...
                max(1, numberRatioValid);
            
            if isempty(validRatioValues)
                
                metrics.MeanPixelRatio750over785 = NaN;
                metrics.MedianPixelRatio750over785 = NaN;
                metrics.P10PixelRatio750over785 = NaN;
                metrics.P90PixelRatio750over785 = NaN;
                
            else
                
                metrics.MeanPixelRatio750over785 = ...
                    mean(validRatioValues, 'omitnan');
                
                metrics.MedianPixelRatio750over785 = ...
                    median(validRatioValues, 'omitnan');
                
                metrics.P10PixelRatio750over785 = ...
                    prctile(validRatioValues, 10);
                
                metrics.P90PixelRatio750over785 = ...
                    prctile(validRatioValues, 90);
            end
            
            if isempty(validRatioScores)
                
                metrics.MeanRatioScore = NaN;
                metrics.MedianRatioScore = NaN;
                metrics.P10RatioScore = NaN;
                metrics.P90RatioScore = NaN;
                
            else
                
                metrics.MeanRatioScore = ...
                    mean(validRatioScores, 'omitnan');
                
                metrics.MedianRatioScore = ...
                    median(validRatioScores, 'omitnan');
                
                metrics.P10RatioScore = ...
                    prctile(validRatioScores, 10);
                
                metrics.P90RatioScore = ...
                    prctile(validRatioScores, 90);
            end
            
            % =============================================================
            % 6. BUILD ASSESSMENT RECORD
            % =============================================================
            
            assessment = struct();
            
            assessment.ClusterID = ...
                C.ID;
            
            assessment.ClusterName = ...
                C.Name;
            
            assessment.ComponentName = ...
                char(string(componentName));
            
            assessment.ReferenceFolder = ...
                referenceData.Folder;
            
            assessment.ReferenceFiles = ...
                referenceData.AcceptedFiles;
            
            assessment.ReferenceCount = ...
                numel(referenceData.AcceptedFiles);
            
            assessment.RejectedFiles = ...
                referenceData.RejectedFiles;
            
            assessment.ReferenceWavenumber = ...
                referenceData.Wavenumber;
            
            assessment.ReferenceSpectra = ...
                referenceData.ReferenceSpectra;
            
            assessment.ConsensusReference = ...
                referenceData.ConsensusReference;
            
            assessment.ReferenceVariability = ...
                referenceData.ReferenceVariability;
            
            assessment.ReferenceConsistencyMedian = ...
                referenceData.ReferenceConsistencyMedian;
            
            assessment.ReferenceConsistencyMinimum = ...
                referenceData.ReferenceConsistencyMinimum;
            
            assessment.PixelIdx = ...
                evaluatedPixelIdx;
            
            assessment.MatchScores = ...
                matchScores;
            
            assessment.MatchScoreMap = ...
                matchScoreMap;
            
            assessment.LowSupportMask = ...
                lowSupportMask;
            
            assessment.IntermediateSupportMask = ...
                intermediateSupportMask;
            
            assessment.HighSupportMask = ...
                highSupportMask;
            
            assessment.LowThreshold = ...
                lowThreshold;
            
            assessment.HighThreshold = ...
                highThreshold;
            
            % -------------------------------------------------------------
            % Ratio result
            % -------------------------------------------------------------
            
            assessment.ReferenceRatios750over785 = ...
                referenceData.ReferenceRatios750over785;
            
            assessment.ReferenceRatioMedian = ...
                referenceData.ReferenceRatioMedian;
            
            assessment.ReferenceRatioP10 = ...
                referenceData.ReferenceRatioP10;
            
            assessment.ReferenceRatioP90 = ...
                referenceData.ReferenceRatioP90;
            
            assessment.PixelRatios750over785 = ...
                pixelRatios;
            
            assessment.RatioScoreValues = ...
                ratioScoreValues;
            
            assessment.RatioScoreMap = ...
                ratioScoreMap;
            
            assessment.RatioValidMask = ...
                ratioValidMask;
            
            assessment.RatioLowMask = ...
                ratioLowMask;
            
            assessment.RatioWeakMask = ...
                ratioWeakMask;
            
            assessment.RatioModerateMask = ...
                ratioModerateMask;
            
            assessment.RatioStrongMask = ...
                ratioStrongMask;
            
            assessment.RatioWindows = struct();
            
            assessment.RatioWindows.Peak750 = ...
                app.Ratio750Window;
            
            assessment.RatioWindows.Peak785 = ...
                app.Ratio785Window;
            
            assessment.RatioWindows.BaselineLeft = ...
                app.RatioBaselineLeftWindow;
            
            assessment.RatioWindows.BaselineRight = ...
                app.RatioBaselineRightWindow;
            
            assessment.Metrics = ...
                metrics;
            
            assessment.ClusterPixelSignature = ...
                app.makeClusterPixelSignature( ...
                C.PixelIdx);
            
            assessment.CreatedAt = ...
                datetime('now');
            
            assessment.IsStale = ...
                false;
            
        end
        
        
        function assessments = makeEmptyComponentAssessments(app)
            
            %#ok<INUSD>
            
            % =============================================================
            % EMPTY REFERENCE-COMPONENT ASSESSMENT ARRAY
            %
            % This must be the single source of truth for the assessment
            % structure schema.
            % =============================================================
            
            assessments = struct( ...
                'ClusterID', {}, ...
                'ClusterName', {}, ...
                'ComponentName', {}, ...
                'ReferenceFolder', {}, ...
                'ReferenceFiles', {}, ...
                'ReferenceCount', {}, ...
                'RejectedFiles', {}, ...
                'ReferenceWavenumber', {}, ...
                'ReferenceSpectra', {}, ...
                'ConsensusReference', {}, ...
                'ReferenceVariability', {}, ...
                'ReferenceConsistencyMedian', {}, ...
                'ReferenceConsistencyMinimum', {}, ...
                'PixelIdx', {}, ...
                'MatchScores', {}, ...
                'MatchScoreMap', {}, ...
                'LowSupportMask', {}, ...
                'IntermediateSupportMask', {}, ...
                'HighSupportMask', {}, ...
                'LowThreshold', {}, ...
                'HighThreshold', {}, ...
                'ReferenceRatios750over785', {}, ...
                'ReferenceRatioMedian', {}, ...
                'ReferenceRatioP10', {}, ...
                'ReferenceRatioP90', {}, ...
                'PixelRatios750over785', {}, ...
                'RatioScoreValues', {}, ...
                'RatioScoreMap', {}, ...
                'RatioValidMask', {}, ...
                'RatioLowMask', {}, ...
                'RatioWeakMask', {}, ...
                'RatioModerateMask', {}, ...
                'RatioStrongMask', {}, ...
                'RatioWindows', {}, ...
                'Metrics', {}, ...
                'ClusterPixelSignature', {}, ...
                'CreatedAt', {}, ...
                'IsStale', {});
            
        end
        
        
        function agreementMatrix = ...
                calculatePCCandidateMapAgreement( ...
                app, ...
                candidateResults)
            
            % =============================================================
            % CROSS-PC MEDOID MAP AGREEMENT
            %
            % Entry (i,j) is ARI between the medoid partitions obtained
            % from candidate PC counts i and j.
            % =============================================================
            
            numberCandidates = ...
                numel(candidateResults);
            
            agreementMatrix = ...
                nan(numberCandidates, numberCandidates);
            
            if numberCandidates < 1
                return
            end
            
            for candidateA = 1:numberCandidates
                
                labelsA = ...
                    candidateResults(candidateA).MedoidLabelsAll;
                
                if isempty(labelsA)
                    continue
                end
                
                agreementMatrix(candidateA, candidateA) = ...
                    1;
                
                for candidateB = candidateA + 1:numberCandidates
                    
                    labelsB = ...
                        candidateResults(candidateB).MedoidLabelsAll;
                    
                    if isempty(labelsB)
                        continue
                    end
                    
                    currentARI = ...
                        app.calculateAdjustedRandIndex( ...
                        labelsA, ...
                        labelsB);
                    
                    agreementMatrix(candidateA, candidateB) = ...
                        currentARI;
                    
                    agreementMatrix(candidateB, candidateA) = ...
                        currentARI;
                end
            end
            
        end
        
        
        function showPCASelectionReview(app)
            
            % =============================================================
            % AUTOMATIC PC-SELECTION REVIEW
            %
            % Displays:
            %   - candidate summary table;
            %   - ARI versus PC count;
            %   - assignment confidence versus PC count;
            %   - explained variance versus PC count;
            %   - selected medoid map;
            %
            % Coarse-only candidates remain visible but are identified.
            % =============================================================
            
            if isempty(app.PCASelectionResults) || ...
                    ~app.PCASelectionCompleted
                
                return
            end
            
            try
                
                if ~isempty(app.PCASelectionFigure) && ...
                        isvalid(app.PCASelectionFigure)
                    
                    delete(app.PCASelectionFigure);
                end
                
            catch
            end
            
            reviewFigure = ...
                uifigure( ...
                'Name', ...
                'Automatic PCA-dimension selection', ...
                'Position', ...
                [80 55 1320 800], ...
                'Color', ...
                [0.96 0.96 0.96]);
            
            app.PCASelectionFigure = ...
                reviewFigure;
            
            mainGrid = ...
                uigridlayout(reviewFigure);
            
            mainGrid.ColumnWidth = {'1.25x', '1x'};
            mainGrid.RowHeight = {'1x', '1x'};
            
            % =============================================================
            % 1. RESULT TABLE
            % =============================================================
            
            tablePanel = ...
                uipanel( ...
                mainGrid, ...
                'Title', ...
                'PCA candidate summary');
            
            tablePanel.Layout.Row = ...
                [1 2];
            
            tablePanel.Layout.Column = ...
                1;
            
            tableGrid = ...
                uigridlayout(tablePanel);
            
            tableGrid.ColumnWidth = {'1x'};
            tableGrid.RowHeight = {'1x', 105};
            
            resultTable = ...
                uitable(tableGrid);
            
            resultTable.Layout.Row = 1;
            
            statusArea = ...
                uitextarea( ...
                tableGrid, ...
                'Editable', ...
                'off');
            
            statusArea.Layout.Row = 2;
            
            % =============================================================
            % 2. PLOTS
            % =============================================================
            
            upperPanel = ...
                uipanel( ...
                mainGrid, ...
                'Title', ...
                'Selection metrics');
            
            upperPanel.Layout.Row = 1;
            upperPanel.Layout.Column = 2;
            
            upperGrid = ...
                uigridlayout(upperPanel);
            
            upperGrid.ColumnWidth = {'1x'};
            upperGrid.RowHeight = {'1x', '1x', '1x'};
            
            ariAxes = ...
                uiaxes(upperGrid);
            
            ariAxes.Layout.Row = 1;
            
            confidenceAxes = ...
                uiaxes(upperGrid);
            
            confidenceAxes.Layout.Row = 2;
            
            varianceAxes = ...
                uiaxes(upperGrid);
            
            varianceAxes.Layout.Row = 3;
            
            mapPanel = ...
                uipanel( ...
                mainGrid, ...
                'Title', ...
                'Selected medoid map');
            
            mapPanel.Layout.Row = 2;
            mapPanel.Layout.Column = 2;
            
            mapGrid = ...
                uigridlayout(mapPanel);
            
            mapGrid.ColumnWidth = {'1x'};
            mapGrid.RowHeight = {'1x'};
            
            mapAxes = ...
                uiaxes(mapGrid);
            
            % =============================================================
            % 3. EXTRACT RESULT VALUES
            % =============================================================
            
            results = ...
                app.PCASelectionResults;
            
            pcValues = ...
                double([results.PCCount]).';
            
            varianceValues = ...
                double([results.ExplainedVariance]).';
            
            ariValues = ...
                double([results.OverallMedianARI]).';
            
            medoidARIValues = ...
                double([results.MedoidMedianARI]).';
            
            confidenceValues = ...
                double([results.MedianConfidence]).';
            
            largestComponentValues = ...
                double( ...
                [results.MedianLargestComponentFraction]).';
            
            isolatedValues = ...
                double( ...
                [results.MedianIsolatedComponentFraction]).';
            
            runValues = ...
                double([results.NumberRuns]).';
            
            stageValues = ...
                string({results.Stage}).';
            
            stabilityValues = ...
                string({results.StabilityLevel}).';
            
            statusValues = ...
                string({results.Status}).';
            
            reasonValues = ...
                string({results.SelectionReason}).';
            
            % =============================================================
            % 4. TABLE
            % =============================================================
            
            resultData = ...
                table( ...
                pcValues, ...
                varianceValues, ...
                runValues, ...
                ariValues, ...
                medoidARIValues, ...
                confidenceValues, ...
                largestComponentValues, ...
                isolatedValues, ...
                stageValues, ...
                stabilityValues, ...
                statusValues, ...
                reasonValues, ...
                'VariableNames', ...
                { ...
                'PCs', ...
                'VariancePercent', ...
                'Runs', ...
                'OverallMedianARI', ...
                'MedoidMedianARI', ...
                'MedianConfidence', ...
                'MedianLargestComponent', ...
                'MedianIsolatedFraction', ...
                'Stage', ...
                'Stability', ...
                'Status', ...
                'Reason'});
            
            resultTable.Data = ...
                resultData;
            
            resultTable.ColumnName = ...
                resultData.Properties.VariableNames;
            
            resultTable.RowName = ...
                {};
            
            try
                
                resultTable.ColumnSortable = ...
                    true;
            catch
            end
            
            % =============================================================
            % 5. ARI PLOT
            % =============================================================
            
            cla(ariAxes, 'reset');
            
            plot( ...
                ariAxes, ...
                pcValues, ...
                ariValues, ...
                '-o', ...
                'LineWidth', ...
                1.4);
            
            hold(ariAxes, 'on');
            
            yline( ...
                ariAxes, ...
                app.PCASelectionMinimumARI, ...
                '--', ...
                sprintf( ...
                'Minimum ARI %.2f', ...
                app.PCASelectionMinimumARI));
            
            xline( ...
                ariAxes, ...
                app.PCASelectionSelectedPC, ...
                '--', ...
                sprintf( ...
                'Selected PC %d', ...
                app.PCASelectionSelectedPC));
            
            hold(ariAxes, 'off');
            
            ariAxes.Box = 'on';
            ariAxes.XGrid = 'on';
            ariAxes.YGrid = 'on';
            
            xlabel(ariAxes, 'Number of PCs');
            ylabel(ariAxes, 'Overall median ARI');
            
            title( ...
                ariAxes, ...
                'Seed stability versus PCA dimension');
            
            % =============================================================
            % 6. CONFIDENCE PLOT
            % =============================================================
            
            cla(confidenceAxes, 'reset');
            
            plot( ...
                confidenceAxes, ...
                pcValues, ...
                confidenceValues, ...
                '-o', ...
                'LineWidth', ...
                1.4);
            
            hold(confidenceAxes, 'on');
            
            xline( ...
                confidenceAxes, ...
                app.PCASelectionSelectedPC, ...
                '--', ...
                sprintf( ...
                'Selected PC %d', ...
                app.PCASelectionSelectedPC));
            
            hold(confidenceAxes, 'off');
            
            confidenceAxes.Box = 'on';
            confidenceAxes.XGrid = 'on';
            confidenceAxes.YGrid = 'on';
            
            xlabel(confidenceAxes, 'Number of PCs');
            ylabel(confidenceAxes, 'Median confidence');
            
            title( ...
                confidenceAxes, ...
                'Centroid-assignment confidence');
            
            % =============================================================
            % 7. EXPLAINED-VARIANCE PLOT
            % =============================================================
            
            cla(varianceAxes, 'reset');
            
            plot( ...
                varianceAxes, ...
                pcValues, ...
                varianceValues, ...
                '-o', ...
                'LineWidth', ...
                1.4);
            
            hold(varianceAxes, 'on');
            
            xline( ...
                varianceAxes, ...
                app.PCASelectionSelectedPC, ...
                '--', ...
                sprintf( ...
                'Selected PC %d', ...
                app.PCASelectionSelectedPC));
            
            hold(varianceAxes, 'off');
            
            varianceAxes.Box = 'on';
            varianceAxes.XGrid = 'on';
            varianceAxes.YGrid = 'on';
            
            xlabel(varianceAxes, 'Number of PCs');
            ylabel(varianceAxes, 'Explained variance (%)');
            
            title( ...
                varianceAxes, ...
                'Cumulative PCA variance');
            
            % =============================================================
            % 8. SELECTED MAP
            % =============================================================
            
            selectedResult = ...
                results(app.PCASelectionSelectedIndex);
            
            selectedLabels = ...
                double(selectedResult.MedoidLabelsAll);
            
            selectedMap = ...
                zeros(size(app.ValidMask2D));
            
            selectedMap(app.MetricPixelIdx) = ...
                selectedLabels;
            
            imagesc( ...
                mapAxes, ...
                selectedMap);
            
            axis(mapAxes, 'image');
            
            mapAxes.YDir = ...
                'normal';
            
            mapAxes.XTick = [];
            mapAxes.YTick = [];
            
            colormap( ...
                mapAxes, ...
                lines(max(2, app.ClusterK)));
            
            clim( ...
                mapAxes, ...
                [0.5 app.ClusterK + 0.5]);
            
            title( ...
                mapAxes, ...
                { ...
                sprintf( ...
                'Selected PC count: %d', ...
                selectedResult.PCCount), ...
                sprintf( ...
                'Variance %.2f%% | ARI %.3f | confidence %.3f', ...
                selectedResult.ExplainedVariance, ...
                selectedResult.OverallMedianARI, ...
                selectedResult.MedianConfidence)}, ...
                'Interpreter', ...
                'none');
            
            % =============================================================
            % 9. STATUS
            % =============================================================
            
            statusArea.Value = { ...
                'AUTOMATIC PCA-DIMENSION SELECTION'; ...
                '================================================'; ...
                sprintf( ...
                'Selected PCs: %d', ...
                selectedResult.PCCount); ...
                sprintf( ...
                'Explained variance: %.2f%%', ...
                selectedResult.ExplainedVariance); ...
                sprintf( ...
                'Overall median ARI: %.3f', ...
                selectedResult.OverallMedianARI); ...
                sprintf( ...
                'Median assignment confidence: %.3f', ...
                selectedResult.MedianConfidence); ...
                ''; ...
                ['Selection: stable ARI plateau + confidence retention ' ...
                '+ lowest accepted dimensionality.']};
            
        end
        
        
        function metadataRoot = getClusterMetadataRoot(app)
            
            % =============================================================
            % OUTPUT ROOT
            % =============================================================
            
            if isempty(app.OutputRoot) || ...
                    ~isfolder(app.OutputRoot)
                
                error( ...
                    ['single_extract OutputRoot is unavailable.' newline ...
                    'Open single_extract through scanspectra.']);
            end
            
            % OutputRoot is already:
            %
            % <workspace>\04_single_extract
            %
            % Do NOT append scan ID or another single_extract folder.
            % =============================================================
            
            metadataRoot = ...
                fullfile( ...
                app.OutputRoot, ...
                'metadata', ...
                'cluster_segmentation');
            
            if ~isfolder(metadataRoot)
                
                [created, messageText] = ...
                    mkdir(metadataRoot);
                
                if ~created
                    
                    error( ...
                        'single_extract:EvidenceFolderCreationFailed', ...
                        ['Could not create cluster metadata folder:' newline ...
                        metadataRoot newline newline ...
                        messageText]);
                end
            end
            
        end
        
        
        function [runFolder, figureFolder] = ...
                createClusterEvidenceRunFolder(app)
            
            % =============================================================
            % CREATE ONE IMMUTABLE EVIDENCE RUN FOLDER
            %
            % Milliseconds are included to prevent accidental overwrite when
            % clustering is rerun multiple times within the same second.
            % =============================================================
            
            metadataRoot = ...
                app.getClusterMetadataRoot();
            
            timestampText = ...
                char( ...
                datetime( ...
                'now', ...
                'Format', ...
                'yyyyMMdd_HHmmss_SSS'));
            
            runFolder = ...
                fullfile( ...
                metadataRoot, ...
                ['run_' timestampText]);
            
            collisionNumber = ...
                1;
            
            originalRunFolder = ...
                runFolder;
            
            while exist(runFolder, 'dir')
                
                runFolder = ...
                    sprintf( ...
                    '%s_%02d', ...
                    originalRunFolder, ...
                    collisionNumber);
                
                collisionNumber = ...
                    collisionNumber + 1;
            end
            
            [created, message] = ...
                mkdir(runFolder);
            
            if ~created
                
                error( ...
                    'single_extract:EvidenceRunFolderCreationFailed', ...
                    ['Could not create evidence run folder:' newline ...
                    runFolder newline newline message]);
            end
            
            figureFolder = ...
                fullfile( ...
                runFolder, ...
                'figures');
            
            [created, message] = ...
                mkdir(figureFolder);
            
            if ~created
                
                error( ...
                    'single_extract:EvidenceFigureFolderCreationFailed', ...
                    ['Could not create evidence figure folder:' newline ...
                    figureFolder newline newline message]);
            end
            
        end
        
        
        function value = ...
                getStructNumericField( ...
                app, ...
                inputStruct, ...
                fieldName) %#ok<INUSD>
            
            value = ...
                NaN;
            
            if ~isstruct(inputStruct) || ...
                    ~isfield(inputStruct, fieldName)
                
                return
            end
            
            rawValue = ...
                inputStruct.(fieldName);
            
            if isempty(rawValue)
                return
            end
            
            rawValue = ...
                double(rawValue);
            
            if isscalar(rawValue)
                
                value = ...
                    rawValue;
            end
            
        end
        
        
        function value = ...
                getStructStringField( ...
                app, ...
                inputStruct, ...
                fieldName) %#ok<INUSD>
            
            value = ...
                "";
            
            if ~isstruct(inputStruct) || ...
                    ~isfield(inputStruct, fieldName)
                
                return
            end
            
            rawValue = ...
                inputStruct.(fieldName);
            
            if isempty(rawValue)
                return
            end
            
            value = ...
                string(rawValue);
            
            if numel(value) > 1
                
                value = ...
                    strjoin(value(:).', ' | ');
            end
            
        end
        
        
        function saveSelectedClusterModel( ...
                app, ...
                modelPath, ...
                params, ...
                selectionSummary)
            
            % =============================================================
            % LIGHTWEIGHT OPERATIONAL MODEL
            %
            % This file is intended for restoring the selected result
            % without rerunning the complete PC sweep.
            % =============================================================
            
            SelectedModel = struct();
            
            SelectedModel.FormatVersion = ...
                app.ClusterEvidenceVersion;
            
            SelectedModel.CreatedAt = ...
                datetime('now');
            
            SelectedModel.ScanName = ...
                app.CurrentScanName;
            
            SelectedModel.ScanID = ...
                app.CurrentScanID;
            
            SelectedModel.SourceMatPath = ...
                app.SourceMatPath;
            
            SelectedModel.Parameters = ...
                params;
            
            SelectedModel.SelectionSummary = ...
                selectionSummary;
            
            SelectedModel.NumPCUsed = ...
                app.NumPCUsed;
            
            SelectedModel.PCAcoeff = ...
                app.PCAcoeff;
            
            SelectedModel.PCAmu = ...
                app.PCAmu;
            
            SelectedModel.PCAExplained = ...
                app.PCAExplained;
            
            SelectedModel.AllPCAScores = ...
                app.AllPCAScores;
            
            SelectedModel.MetricPixelIdx = ...
                app.MetricPixelIdx;
            
            SelectedModel.MetricLabels = ...
                app.MetricLabels;
            
            SelectedModel.ClusterCentroids = ...
                app.ClusterCentroids;
            
            SelectedModel.TrainingLocalIdx = ...
                app.TrainingLocalIdx;
            
            SelectedModel.TrainingLinearIdx = ...
                app.TrainingLinearIdx;
            
            SelectedModel.PixelConfidence = ...
                app.PixelConfidence;
            
            SelectedModel.PixelConfidenceMap = ...
                app.PixelConfidenceMap;
            
            SelectedModel.ClusterLabelMap = ...
                app.ClusterLabelMap;
            
            SelectedModel.Clusters = ...
                app.Clusters;
            
            SelectedModel.SelectedRun = ...
                app.ClusterSelectedRun;
            
            SelectedModel.SelectedSeed = ...
                app.ClusterSelectedSeed;
            
            SelectedModel.OverallMedianARI = ...
                app.ClusterOverallMedianARI;
            
            SelectedModel.MedoidMedianARI = ...
                app.ClusterMedoidMedianARI;
            
            SelectedModel.StabilityLevel = ...
                app.ClusterStabilityLevel;
            
            save( ...
                modelPath, ...
                'SelectedModel', ...
                '-v7.3');
            
        end
        
        
        function labels = makeDisplayLabels(app, names) %#ok<INUSD>
            
            names = ...
                string(names(:));
            
            labels = ...
                names;
            
            % Keep the true text exactly as it is.
            % MATLAB rendering will be forced to Interpreter='none'
            % wherever these labels are used.
            
        end
        
        
        function safeDeleteSingleExtractFigure(app, figureHandle) %#ok<INUSD>
            
            try
                
                if ~isempty(figureHandle) && ...
                        isgraphics(figureHandle)
                    
                    delete(figureHandle);
                end
                
            catch
                % Cleanup must never interrupt extraction completion.
            end
            
        end
        
        
        function outputPaths = exportSingleExtractMaps(app, results)
            
            % =============================================================
            % EXPORT ONE QC MAP FIGURE PER SCAN
            %
            % Output structure:
            %
            % <SingleExtractOutputRoot>\
            %   _extraction_maps\
            %       <ScanName>\
            %           <ScanName>_extraction_maps.png
            %
            % Figures are generated invisibly and deleted immediately after
            % saving so batch extraction does not leave many open windows.
            %
            % Representative-region colors are categorical/discrete:
            % color encodes region identity only, not magnitude.
            % =============================================================
            
            outputPaths = ...
                strings(0, 1);
            
            if isempty(results)
                return
            end
            
            % =============================================================
            % 1. MASTER MAP-QC ROOT
            % =============================================================
            
            mapRoot = ...
                fullfile( ...
                app.SingleExtractOutputRoot, ...
                '_extraction_maps');
            
            if ~exist(mapRoot, 'dir')
                
                [createdOK, createMessage] = ...
                    mkdir(mapRoot);
                
                if ~createdOK
                    
                    error( ...
                        'single_extract:MapRootCreationFailed', ...
                        sprintf([ ...
                        'Could not create extraction-map folder:\n\n%s\n\n%s'], ...
                        mapRoot, ...
                        createMessage));
                end
            end
            
            % =============================================================
            % 2. ONE FIGURE PER SCAN
            % =============================================================
            
            for resultNumber = 1:numel(results)
                
                R = ...
                    results(resultNumber);
                
                sampleIndex = ...
                    R.SampleIndex;
                
                if ~isfinite(sampleIndex) || ...
                        sampleIndex < 1 || ...
                        sampleIndex > numel(app.SingleExtractSamples)
                    
                    warning( ...
                        'single_extract:InvalidMapSampleIndex', ...
                        'Skipping map export for an invalid sample index.');
                    
                    continue
                end
                
                S = ...
                    app.SingleExtractSamples(sampleIndex);
                
                sampleName = ...
                    char(string(R.SampleName));
                
                safeSampleName = ...
                    app.makeSafeFileName( ...
                    sampleName);
                
                if isempty(safeSampleName)
                    
                    safeSampleName = ...
                        sprintf( ...
                        'scan_%03d', ...
                        resultNumber);
                end
                
                % =========================================================
                % 3. PER-SCAN QC FOLDER
                % =========================================================
                
                scanMapFolder = ...
                    fullfile( ...
                    mapRoot, ...
                    safeSampleName);
                
                if ~exist(scanMapFolder, 'dir')
                    
                    [createdOK, createMessage] = ...
                        mkdir(scanMapFolder);
                    
                    if ~createdOK
                        
                        warning( ...
                            'single_extract:ScanMapFolderCreationFailed', ...
                            sprintf([ ...
                            'Could not create map folder for "%s":\n%s'], ...
                            sampleName, ...
                            createMessage));
                        
                        continue
                    end
                end
                
                % =========================================================
                % 4. INPUT MAPS
                % =========================================================
                
                bandMap = ...
                    double( ...
                    S.BandMap);
                
                clusterMask = ...
                    logical( ...
                    S.ClusterPackage.Map.ClusterMask2D);
                
                if ~isequal( ...
                        size(bandMap), ...
                        size(clusterMask))
                    
                    warning( ...
                        'single_extract:MapSizeMismatch', ...
                        sprintf([ ...
                        'Skipping map export for "%s" because BandMap and ' ...
                        'ClusterMask2D have different sizes.'], ...
                        sampleName));
                    
                    continue
                end
                
                % =========================================================
                % 5. REPRESENTATIVE LABEL MAP
                %
                % 0 = outside selected cluster
                % 1..N = representative region IDs
                % =========================================================
                
                representativeMap = ...
                    zeros( ...
                    size(clusterMask), ...
                    'double');
                
                numberRepresentatives = ...
                    R.NumberRepresentatives;
                
                for representativeNumber = ...
                        1:numberRepresentatives
                    
                    pixelIdx = ...
                        double( ...
                        R.SpatialGroups{representativeNumber}(:));
                    
                    if isempty(pixelIdx)
                        continue
                    end
                    
                    validPixelIdx = ...
                        pixelIdx( ...
                        pixelIdx >= 1 & ...
                        pixelIdx <= numel(representativeMap));
                    
                    representativeMap(validPixelIdx) = ...
                        representativeNumber;
                end
                
                % =========================================================
                % 6. CREATE INVISIBLE FIGURE
                %
                % Figure never appears on desktop.
                % =========================================================
                
                mapFigure = ...
                    figure( ...
                    'Visible', ...
                    'off', ...
                    'Color', ...
                    'w', ...
                    'Position', ...
                    [100 100 1450 540]);
                
                cleanupFigure = ...
                    onCleanup( ...
                    @() app.safeDeleteSingleExtractFigure(mapFigure)); %#ok<NASGU>
                
                layout = ...
                    tiledlayout( ...
                    mapFigure, ...
                    1, ...
                    3, ...
                    'TileSpacing', ...
                    'compact', ...
                    'Padding', ...
                    'compact');
                
                % =========================================================
                % PANEL 1 — BAND MAP
                % =========================================================
                
                ax1 = ...
                    nexttile(layout);
                
                imagesc( ...
                    ax1, ...
                    bandMap);
                
                axis(ax1, 'image');
                axis(ax1, 'off');
                
                ax1.YDir = ...
                    'normal';
                
                colormap( ...
                    ax1, ...
                    hot(256));
                
                bandValues = ...
                    bandMap(isfinite(bandMap));
                
                if ~isempty(bandValues)
                    
                    bandLow = ...
                        prctile( ...
                        bandValues, ...
                        1);
                    
                    bandHigh = ...
                        prctile( ...
                        bandValues, ...
                        99);
                    
                    if isfinite(bandLow) && ...
                            isfinite(bandHigh) && ...
                            bandLow < bandHigh
                        
                        clim( ...
                            ax1, ...
                            [bandLow bandHigh]);
                    end
                end
                
                colorbar(ax1);
                
                title( ...
                    ax1, ...
                    sprintf( ...
                    'Band map | %.0f-%.0f cm^{-1}', ...
                    app.SingleExtractBandRange(1), ...
                    app.SingleExtractBandRange(2)), ...
                    'Interpreter', ...
                    'none');
                
                % =========================================================
                % PANEL 2 — MASKED BAND
                %
                % Outside selected cluster = black.
                % =========================================================
                
                ax2 = ...
                    nexttile(layout);
                
                maskedBand = ...
                    bandMap;
                
                maskedBand(~clusterMask) = ...
                    NaN;
                
                imageHandle = ...
                    imagesc( ...
                    ax2, ...
                    maskedBand);
                
                imageHandle.AlphaData = ...
                    double(isfinite(maskedBand));
                
                axis(ax2, 'image');
                axis(ax2, 'off');
                
                ax2.YDir = ...
                    'normal';
                
                ax2.Color = ...
                    [0 0 0];
                
                colormap( ...
                    ax2, ...
                    gray(256));
                
                maskedValues = ...
                    maskedBand(isfinite(maskedBand));
                
                if ~isempty(maskedValues)
                    
                    maskedLow = ...
                        prctile( ...
                        maskedValues, ...
                        1);
                    
                    maskedHigh = ...
                        prctile( ...
                        maskedValues, ...
                        99);
                    
                    if isfinite(maskedLow) && ...
                            isfinite(maskedHigh) && ...
                            maskedLow < maskedHigh
                        
                        clim( ...
                            ax2, ...
                            [maskedLow maskedHigh]);
                    end
                end
                
                colorbar(ax2);
                
                title( ...
                    ax2, ...
                    sprintf( ...
                    'Masked band | cluster pixels = %d', ...
                    nnz(clusterMask)), ...
                    'Interpreter', ...
                    'none');
                
                % =========================================================
                % PANEL 3 — REPRESENTATIVE REGIONS
                %
                % IMPORTANT:
                % Region colors are categorical.
                % The numeric region ID is NOT a continuous measurement.
                % =========================================================
                
                ax3 = ...
                    nexttile(layout);
                
                representativeDisplay = ...
                    representativeMap;
                
                representativeDisplay(~clusterMask) = ...
                    NaN;
                
                regionHandle = ...
                    imagesc( ...
                    ax3, ...
                    representativeDisplay);
                
                regionHandle.AlphaData = ...
                    double(clusterMask);
                
                axis(ax3, 'image');
                axis(ax3, 'off');
                
                ax3.YDir = ...
                    'normal';
                
                ax3.Color = ...
                    [0 0 0];
                
                % ---------------------------------------------------------
                % Discrete categorical palette
                %
                % lines() is categorical and avoids the false impression that
                % larger region IDs represent larger values.
                %
                % For very many regions, repeat a sufficiently large palette.
                % ---------------------------------------------------------
                
                numberColors = ...
                    max( ...
                    1, ...
                    numberRepresentatives);
                
                discreteMap = ...
                    lines(numberColors);
                
                colormap( ...
                    ax3, ...
                    discreteMap);
                
                if numberRepresentatives > 0
                    
                    clim( ...
                        ax3, ...
                        [0.5 numberRepresentatives + 0.5]);
                end
                
                % ---------------------------------------------------------
                % Draw boundaries so adjacent regions remain distinguishable
                % even when categorical colors look similar.
                % ---------------------------------------------------------
                
                hold(ax3, 'on');
                
                for representativeNumber = ...
                        1:numberRepresentatives
                    
                    currentMask = ...
                        representativeMap == ...
                        representativeNumber;
                    
                    if ~any(currentMask(:))
                        continue
                    end
                    
                    contour( ...
                        ax3, ...
                        currentMask, ...
                        [0.5 0.5], ...
                        'k', ...
                        'LineWidth', ...
                        0.35, ...
                        'HitTest', ...
                        'off');
                end
                
                hold(ax3, 'off');
                
                % No colorbar here:
                % representative ID is categorical, not quantitative.
                
                title( ...
                    ax3, ...
                    sprintf( ...
                    'Representative regions | n = %d', ...
                    numberRepresentatives), ...
                    'Interpreter', ...
                    'none');
                
                % =========================================================
                % 7. OVERALL TITLE
                % =========================================================
                
                title( ...
                    layout, ...
                    { ...
                    'Extraction spatial QC'; ...
                    sampleName; ...
                    sprintf( ...
                    'Normal=%d | Undersized=%d | Singleton=%d | Oversized=%d', ...
                    R.NormalCount, ...
                    R.UndersizedCount, ...
                    R.SingletonCount, ...
                    R.OversizedCount)}, ...
                    'Interpreter', ...
                    'none');
                
                % =========================================================
                % 8. SAVE ONE FIGURE PER SCAN
                % =========================================================
                
                outputPath = ...
                    fullfile( ...
                    scanMapFolder, ...
                    [ ...
                    safeSampleName ...
                    '_extraction_maps.png']);
                
                exportgraphics( ...
                    mapFigure, ...
                    outputPath, ...
                    'Resolution', ...
                    300);
                
                outputPaths(end + 1, 1) = ...
                    string(outputPath); %#ok<AGROW>
                
                % =========================================================
                % 9. FIGURE IS DELETED AUTOMATICALLY
                %
                % cleanupFigure calls safeDeleteSingleExtractFigure().
                % Explicit clear triggers cleanup now rather than at function exit.
                % =========================================================
                
                clear cleanupFigure
                
            end
            
        end
        
        
        function savePCSelectionMetricsEvidence( ...
                app, ...
                outputPath)
            
            results = ...
                app.PCASelectionResults;
            
            if isempty(results)
                return
            end
            
            pcValues = ...
                double([results.PCCount]);
            
            varianceValues = ...
                double([results.ExplainedVariance]);
            
            ariValues = ...
                double([results.OverallMedianARI]);
            
            confidenceValues = ...
                double([results.MedianConfidence]);
            
            evidenceFigure = ...
                figure( ...
                'Visible', ...
                'off', ...
                'Color', ...
                'w', ...
                'Position', ...
                [100 100 1050 900]);
            
            cleanupObject = ...
                onCleanup( ...
                @() delete(evidenceFigure)); %#ok<NASGU>
            
            layout = ...
                tiledlayout( ...
                evidenceFigure, ...
                3, ...
                1, ...
                'TileSpacing', ...
                'compact', ...
                'Padding', ...
                'compact');
            
            ariAxis = ...
                nexttile(layout);
            
            plot( ...
                ariAxis, ...
                pcValues, ...
                ariValues, ...
                '-o', ...
                'LineWidth', ...
                1.5);
            
            hold(ariAxis, 'on');
            
            yline( ...
                ariAxis, ...
                app.PCASelectionMinimumARI, ...
                '--', ...
                sprintf( ...
                'Minimum ARI %.2f', ...
                app.PCASelectionMinimumARI));
            
            xline( ...
                ariAxis, ...
                app.PCASelectionSelectedPC, ...
                '--', ...
                sprintf( ...
                'Selected PC %d', ...
                app.PCASelectionSelectedPC));
            
            hold(ariAxis, 'off');
            
            grid(ariAxis, 'on');
            
            xlabel(ariAxis, 'Number of PCs');
            ylabel(ariAxis, 'Overall median ARI');
            
            title( ...
                ariAxis, ...
                'Seed stability versus PCA dimension');
            
            confidenceAxis = ...
                nexttile(layout);
            
            plot( ...
                confidenceAxis, ...
                pcValues, ...
                confidenceValues, ...
                '-o', ...
                'LineWidth', ...
                1.5);
            
            hold(confidenceAxis, 'on');
            
            xline( ...
                confidenceAxis, ...
                app.PCASelectionSelectedPC, ...
                '--', ...
                sprintf( ...
                'Selected PC %d', ...
                app.PCASelectionSelectedPC));
            
            hold(confidenceAxis, 'off');
            
            grid(confidenceAxis, 'on');
            
            xlabel(confidenceAxis, 'Number of PCs');
            ylabel(confidenceAxis, 'Median confidence');
            
            title( ...
                confidenceAxis, ...
                'Centroid-assignment confidence');
            
            varianceAxis = ...
                nexttile(layout);
            
            plot( ...
                varianceAxis, ...
                pcValues, ...
                varianceValues, ...
                '-o', ...
                'LineWidth', ...
                1.5);
            
            hold(varianceAxis, 'on');
            
            xline( ...
                varianceAxis, ...
                app.PCASelectionSelectedPC, ...
                '--', ...
                sprintf( ...
                'Selected PC %d', ...
                app.PCASelectionSelectedPC));
            
            hold(varianceAxis, 'off');
            
            grid(varianceAxis, 'on');
            
            xlabel(varianceAxis, 'Number of PCs');
            ylabel(varianceAxis, 'Explained variance (%)');
            
            title( ...
                varianceAxis, ...
                'Cumulative PCA variance');
            
            title( ...
                layout, ...
                { ...
                app.CurrentScanName, ...
                'Automatic PCA-dimension selection evidence'}, ...
                'Interpreter', ...
                'none');
            
            exportgraphics( ...
                evidenceFigure, ...
                outputPath, ...
                'Resolution', ...
                300);
            
        end
        
        

        function splitSelectedCluster(app)
            
            % =============================================================
            % ADAPTIVE, STOP-CONTROLLED LOCAL SPLIT
            %
            % Scientific design:
            %   - use the existing selected global PCA space;
            %   - begin with five independent seeds;
            %   - extend to ten only when initial stability is ambiguous;
            %   - stop unstable, tiny, unclear, or excessively deep splits;
            %   - apply the actual ARI-medoid partition;
            %   - retain the original parent as inactive history.
            % =============================================================
            
            ids = ...
                app.SelectedClusterIDs;
            
            if numel(ids) ~= 1
                
                uialert( ...
                    app.UIFigure, ...
                    'Select exactly one active cluster to split.', ...
                    'Split requires one cluster');
                
                return
            end
            
            parentIndex = ...
                app.findClusterIndexByID(ids(1));
            
            if isempty(parentIndex) || ...
                    ~app.Clusters(parentIndex).Active
                
                uialert( ...
                    app.UIFigure, ...
                    'The selected cluster is not active.', ...
                    'Invalid cluster');
                
                return
            end
            
            parent = ...
                app.Clusters(parentIndex);
            
            % =============================================================
            % 1. HIERARCHICAL STOP RULE
            % =============================================================
            
            parentDepth = ...
                app.getClusterSplitDepth( ...
                parent.ID);
            
            if parentDepth >= ...
                    app.SplitMaximumDepth
                
                uialert( ...
                    app.UIFigure, ...
                    { ...
                    sprintf( ...
                    '%s is already at split depth %d.', ...
                    parent.Name, ...
                    parentDepth); ...
                    ''; ...
                    sprintf( ...
                    'Maximum allowed split depth: %d', ...
                    app.SplitMaximumDepth); ...
                    ''; ...
                    ['Further subdivision is stopped to prevent iterative ' ...
                    'over-segmentation.']; ...
                    ['Use pixel purity or merge existing children instead.']}, ...
                    'Maximum split depth reached');
                
                return
            end
            
            % =============================================================
            % 2. GET PARENT DATA IN EXISTING GLOBAL PCA SPACE
            % =============================================================
            
            rowMask = ...
                ismember( ...
                app.MetricPixelIdx, ...
                parent.PixelIdx);
            
            localScores = ...
                double( ...
                app.AllPCAScores(rowMask, :));
            
            localPixelIdx = ...
                double( ...
                app.MetricPixelIdx(rowMask));
            
            numberPixels = ...
                size(localScores, 1);
            
            if numberPixels < ...
                    2 * app.SplitMinimumChildPixels
                
                uialert( ...
                    app.UIFigure, ...
                    sprintf( ...
                    ['%s contains only %d pixels. At least %d pixels are ' ...
                    'required before attempting a two-child split.'], ...
                    parent.Name, ...
                    numberPixels, ...
                    2 * app.SplitMinimumChildPixels), ...
                    'Cluster too small to split');
                
                return
            end
            
            if isempty(localScores) || ...
                    size(localScores, 2) < 1 || ...
                    any(~isfinite(localScores(:)))
                
                uialert( ...
                    app.UIFigure, ...
                    ['The selected cluster does not contain a complete ' ...
                    'finite PCA-score matrix.'], ...
                    'Invalid split input');
                
                return
            end
            
            % =============================================================
            % 3. OPTIONAL PURITY STOP RULE
            %
            % A cluster already showing a conservative pure-cell or
            % pure-background composition normally does not need further
            % spectral subdivision for the current cell/background goal.
            % =============================================================
            
            if app.PixelAssessmentReady && ...
                    isfield(parent.Metrics, ...
                    'ConfidentCellFraction') && ...
                    isfield(parent.Metrics, ...
                    'ConfidentBackgroundFraction')
                
                cellFraction = ...
                    parent.Metrics.ConfidentCellFraction;
                
                backgroundFraction = ...
                    parent.Metrics.ConfidentBackgroundFraction;
                
                cellPure = ...
                    cellFraction >= 0.90 && ...
                    backgroundFraction <= 0.05;
                
                backgroundPure = ...
                    backgroundFraction >= 0.95 && ...
                    cellFraction <= 0.02;
                
                if cellPure || backgroundPure
                    
                    if cellPure
                        
                        purityDescription = ...
                            sprintf( ...
                            'Cell %.1f%% | Background %.1f%%', ...
                            100 * cellFraction, ...
                            100 * backgroundFraction);
                        
                    else
                        
                        purityDescription = ...
                            sprintf( ...
                            'Background %.1f%% | Cell %.1f%%', ...
                            100 * backgroundFraction, ...
                            100 * cellFraction);
                    end
                    
                    purityChoice = ...
                        uiconfirm( ...
                        app.UIFigure, ...
                        { ...
                        sprintf( ...
                        '%s is already compositionally clear.', ...
                        parent.Name); ...
                        ''; ...
                        purityDescription; ...
                        ''; ...
                        ['Further splitting is not required for the current ' ...
                        'cell/background separation objective.']; ...
                        'Run the split only when a separate spectral question exists.'}, ...
                        'Purity-based split stop', ...
                        'Options', ...
                        {'Keep cluster intact', 'Split anyway'}, ...
                        'DefaultOption', ...
                        'Keep cluster intact', ...
                        'CancelOption', ...
                        'Keep cluster intact');
                    
                    if ~strcmp(purityChoice, 'Split anyway')
                        return
                    end
                end
            end
            
            % =============================================================
            % 4. REQUEST NUMBER OF CHILDREN
            % =============================================================
            
            maximumSplitByPixels = ...
                floor( ...
                numberPixels / ...
                app.SplitMinimumChildPixels);
            
            maximumSplit = ...
                min( ...
                6, ...
                maximumSplitByPixels);
            
            maximumSplit = ...
                max(2, maximumSplit);
            
            answer = ...
                inputdlg( ...
                { ...
                sprintf( ...
                'Number of spectral children (2-%d)', ...
                maximumSplit)}, ...
                ['Adaptive split — ' parent.Name], ...
                [1 58], ...
                {'2'});
            
            if isempty(answer)
                return
            end
            
            numberOfSubclusters = ...
                round( ...
                str2double(answer{1}));
            
            if ~isfinite(numberOfSubclusters) || ...
                    numberOfSubclusters < 2 || ...
                    numberOfSubclusters > maximumSplit
                
                uialert( ...
                    app.UIFigure, ...
                    sprintf( ...
                    'Enter an integer between 2 and %d.', ...
                    maximumSplit), ...
                    'Invalid number of children');
                
                return
            end
            
            minimumPixelsForRequestedSplit = ...
                numberOfSubclusters * ...
                app.SplitMinimumChildPixels;
            
            if numberPixels < ...
                    minimumPixelsForRequestedSplit
                
                uialert( ...
                    app.UIFigure, ...
                    sprintf( ...
                    ['The requested split needs at least %d pixels using ' ...
                    'the current minimum-child rule. Parent pixels: %d.'], ...
                    minimumPixelsForRequestedSplit, ...
                    numberPixels), ...
                    'Insufficient parent pixels');
                
                return
            end
            
            % =============================================================
            % 5. CONFIRM QUICK ADAPTIVE ASSESSMENT
            % =============================================================
            
            choice = ...
                uiconfirm( ...
                app.UIFigure, ...
                { ...
                sprintf( ...
                'Assess splitting %s into %d children?', ...
                parent.Name, ...
                numberOfSubclusters); ...
                ''; ...
                sprintf( ...
                'Parent pixels: %d', ...
                numberPixels); ...
                sprintf( ...
                'Current split depth: %d / %d', ...
                parentDepth, ...
                app.SplitMaximumDepth); ...
                sprintf( ...
                'Existing PCA dimensions: %d', ...
                size(localScores, 2)); ...
                ''; ...
                sprintf( ...
                'Quick assessment: %d independent runs', ...
                app.SplitQuickRuns); ...
                sprintf( ...
                'Ambiguous result: extend automatically to %d total runs', ...
                app.SplitExtendedRuns); ...
                ''; ...
                'PCA will not be recalculated.'}, ...
                'Adaptive cluster split', ...
                'Options', ...
                {'Assess split', 'Cancel'}, ...
                'DefaultOption', ...
                'Assess split', ...
                'CancelOption', ...
                'Cancel');
            
            if ~strcmp(choice, 'Assess split')
                return
            end
            
            app.UIFigure.Pointer = ...
                'watch';
            
            pointerCleanup = ...
                onCleanup( ...
                @() set(app.UIFigure, ...
                'Pointer', 'arrow')); %#ok<NASGU>
            
            baseSeed = ...
                app.ClusterRandomSeed + ...
                10000 + ...
                100 * parent.ID + ...
                10 * numberOfSubclusters;
            
            % =============================================================
            % 6. QUICK FIVE-RUN ASSESSMENT
            % =============================================================
            
            app.TextArea.Value = { ...
                'ADAPTIVE SPLIT — QUICK ASSESSMENT'; ...
                '================================================'; ...
                ''; ...
                ['Parent: ' parent.Name]; ...
                sprintf( ...
                'Requested children: %d', ...
                numberOfSubclusters); ...
                sprintf( ...
                'Runs: %d', ...
                app.SplitQuickRuns); ...
                ''; ...
                'Using the existing global PCA space.'};
            
            drawnow;
            
            try
                
                quickResults = ...
                    app.runLocalSplitKMeansRuns( ...
                    localScores, ...
                    numberOfSubclusters, ...
                    1, ...
                    app.SplitQuickRuns, ...
                    baseSeed);
                
                quickStability = ...
                    app.evaluateKMeansStability( ...
                    quickResults);
                
            catch ME
                
                uialert( ...
                    app.UIFigure, ...
                    getReport( ...
                    ME, ...
                    'extended', ...
                    'hyperlinks', ...
                    'off'), ...
                    'Quick split assessment failed');
                
                return
            end
            
            quickARI = ...
                quickStability.OverallMedianARI;
            
            % =============================================================
            % 7. ADAPTIVE DECISION
            % =============================================================
            
            if quickARI >= ...
                    app.SplitQuickAcceptARI
                
                finalStability = ...
                    quickStability;
                
                decisionStage = ...
                    'Quick five-run acceptance';
                
            elseif quickARI < ...
                    app.SplitQuickRejectARI
                
                app.LastSplitStability = struct( ...
                    'CreatedAt', ...
                    datetime('now'), ...
                    'ParentID', ...
                    parent.ID, ...
                    'ParentName', ...
                    parent.Name, ...
                    'Accepted', ...
                    false, ...
                    'Decision', ...
                    'Rejected after quick assessment', ...
                    'NumberChildren', ...
                    numberOfSubclusters, ...
                    'RunsUsed', ...
                    app.SplitQuickRuns, ...
                    'OverallMedianARI', ...
                    quickARI, ...
                    'RunResults', ...
                    quickStability.RunResults, ...
                    'PairwiseARI', ...
                    quickStability.PairwiseARI);
                
                uialert( ...
                    app.UIFigure, ...
                    { ...
                    'The proposed split was not applied.'; ...
                    ''; ...
                    sprintf( ...
                    'Quick runs: %d', ...
                    app.SplitQuickRuns); ...
                    sprintf( ...
                    'Overall median pairwise ARI: %.3f', ...
                    quickARI); ...
                    sprintf( ...
                    'Automatic rejection threshold: %.2f', ...
                    app.SplitQuickRejectARI); ...
                    ''; ...
                    ['The child partition depends too strongly on random ' ...
                    'initialization.']; ...
                    ['Keep the parent intact, try a different number of ' ...
                    'children, or continue to pixel purity assessment.']}, ...
                    'Unstable split stopped');
                
                return
                
            else
                
                % =========================================================
                % 8. EXTEND ONLY AMBIGUOUS QUICK RESULTS
                % =========================================================
                
                app.TextArea.Value = { ...
                    'ADAPTIVE SPLIT — EXTENDED ASSESSMENT'; ...
                    '================================================'; ...
                    ''; ...
                    ['Parent: ' parent.Name]; ...
                    sprintf( ...
                    'Quick ARI: %.3f', ...
                    quickARI); ...
                    sprintf( ...
                    'Extending from %d to %d total runs...', ...
                    app.SplitQuickRuns, ...
                    app.SplitExtendedRuns)};
                
                drawnow;
                
                try
                    
                    extraResults = ...
                        app.runLocalSplitKMeansRuns( ...
                        localScores, ...
                        numberOfSubclusters, ...
                        app.SplitQuickRuns + 1, ...
                        app.SplitExtendedRuns, ...
                        baseSeed);
                    
                    combinedResults = ...
                        [ ...
                        quickResults(:); ...
                        extraResults(:)];
                    
                    finalStability = ...
                        app.evaluateKMeansStability( ...
                        combinedResults);
                    
                catch ME
                    
                    uialert( ...
                        app.UIFigure, ...
                        getReport( ...
                        ME, ...
                        'extended', ...
                        'hyperlinks', ...
                        'off'), ...
                        'Extended split assessment failed');
                    
                    return
                end
                
                decisionStage = ...
                    'Extended ten-run assessment';
                
                if finalStability.OverallMedianARI < ...
                        app.SplitFinalAcceptARI
                    
                    app.LastSplitStability = struct( ...
                        'CreatedAt', ...
                        datetime('now'), ...
                        'ParentID', ...
                        parent.ID, ...
                        'ParentName', ...
                        parent.Name, ...
                        'Accepted', ...
                        false, ...
                        'Decision', ...
                        'Rejected after extended assessment', ...
                        'NumberChildren', ...
                        numberOfSubclusters, ...
                        'RunsUsed', ...
                        app.SplitExtendedRuns, ...
                        'OverallMedianARI', ...
                        finalStability.OverallMedianARI, ...
                        'RunResults', ...
                        finalStability.RunResults, ...
                        'PairwiseARI', ...
                        finalStability.PairwiseARI);
                    
                    uialert( ...
                        app.UIFigure, ...
                        { ...
                        'The proposed split was not applied.'; ...
                        ''; ...
                        sprintf( ...
                        'Extended runs: %d', ...
                        app.SplitExtendedRuns); ...
                        sprintf( ...
                        'Overall median pairwise ARI: %.3f', ...
                        finalStability.OverallMedianARI); ...
                        sprintf( ...
                        'Required final ARI: %.2f', ...
                        app.SplitFinalAcceptARI); ...
                        ''; ...
                        ['Repeated subdivision is stopped because a stable ' ...
                        'child structure was not demonstrated.']; ...
                        'Keep the parent or use pixel-level purity.'}, ...
                        'Split stability insufficient');
                    
                    return
                end
            end
            
            % =============================================================
            % 9. MEDOID-SPLIT QC
            % =============================================================
            
            try
                
                splitQC = ...
                    app.evaluateSelectedLocalSplit( ...
                    localScores, ...
                    localPixelIdx, ...
                    numberOfSubclusters, ...
                    finalStability);
                
            catch ME
                
                uialert( ...
                    app.UIFigure, ...
                    ME.message, ...
                    'Split QC failed');
                
                return
            end
            
            if ~splitQC.PassesIntegrityRules
                
                uialert( ...
                    app.UIFigure, ...
                    ['The selected medoid solution did not preserve a valid ' ...
                    'one-to-one partition of all parent pixels.'], ...
                    'Split integrity failure');
                
                return
            end
            
            if ~splitQC.PassesSizeRule
                
                uialert( ...
                    app.UIFigure, ...
                    { ...
                    'The selected medoid split contains an undersized child.'; ...
                    ''; ...
                    sprintf( ...
                    'Child sizes: %s', ...
                    mat2str(splitQC.ChildSizes(:).')); ...
                    sprintf( ...
                    'Required minimum per child: %d pixels', ...
                    splitQC.MinimumRequiredPixels); ...
                    ''; ...
                    ['The split was stopped because the small child may ' ...
                    'represent an outlier or over-segmentation.']}, ...
                    'Child-size stop rule');
                
                return
            end
            
            if ~splitQC.PassesConfidenceRule
                
                uialert( ...
                    app.UIFigure, ...
                    { ...
                    'The selected medoid split has weak child separation.'; ...
                    ''; ...
                    sprintf( ...
                    'Median child-assignment confidence: %.3f', ...
                    splitQC.MedianConfidence); ...
                    sprintf( ...
                    'Required minimum: %.2f', ...
                    app.SplitMinimumMedianConfidence); ...
                    ''; ...
                    ['A stable but weakly separated partition is not enough ' ...
                    'to justify another cluster level.']}, ...
                    'Weak split separation');
                
                return
            end
            
            % =============================================================
            % 10. FINAL USER REVIEW
            % =============================================================
            
            finalChoice = ...
                uiconfirm( ...
                app.UIFigure, ...
                { ...
                'The proposed split passed all automatic stop rules.'; ...
                ''; ...
                sprintf( ...
                'Assessment: %s', ...
                decisionStage); ...
                sprintf( ...
                'Runs used: %d', ...
                numel(finalStability.RunResults)); ...
                sprintf( ...
                'Overall median pairwise ARI: %.3f', ...
                finalStability.OverallMedianARI); ...
                sprintf( ...
                'Selected medoid run: %d', ...
                finalStability.SelectedRun); ...
                sprintf( ...
                'Selected medoid seed: %d', ...
                finalStability.SelectedSeed); ...
                sprintf( ...
                'Median child confidence: %.3f', ...
                splitQC.MedianConfidence); ...
                sprintf( ...
                'Child sizes: %s', ...
                mat2str(splitQC.ChildSizes(:).')); ...
                ''; ...
                'Apply this split?'}, ...
                'Accept stable split', ...
                'Options', ...
                {'Apply split', 'Keep parent'}, ...
                'DefaultOption', ...
                'Apply split', ...
                'CancelOption', ...
                'Keep parent');
            
            if ~strcmp(finalChoice, 'Apply split')
                
                app.LastSplitStability = struct( ...
                    'CreatedAt', ...
                    datetime('now'), ...
                    'ParentID', ...
                    parent.ID, ...
                    'ParentName', ...
                    parent.Name, ...
                    'Accepted', ...
                    false, ...
                    'Decision', ...
                    'Passed QC but rejected by user', ...
                    'NumberChildren', ...
                    numberOfSubclusters, ...
                    'RunsUsed', ...
                    numel(finalStability.RunResults), ...
                    'OverallMedianARI', ...
                    finalStability.OverallMedianARI, ...
                    'MedianConfidence', ...
                    splitQC.MedianConfidence, ...
                    'RunResults', ...
                    finalStability.RunResults, ...
                    'PairwiseARI', ...
                    finalStability.PairwiseARI);
                
                return
            end
            
            % =============================================================
            % 11. STORE HISTORY BEFORE MODIFYING CLUSTERS
            % =============================================================
            
            app.pushClusterHistory();
            
            app.Clusters(parentIndex).Active = ...
                false;
            
            app.Clusters(parentIndex).Class = ...
                'Split parent';
            
            % =============================================================
            % 12. CREATE CHILDREN FROM THE ACTUAL MEDOID LABELS
            % =============================================================
            
            selectedLabels = ...
                double( ...
                splitQC.SelectedLabels);
            
            childColors = ...
                app.makeSplitColors( ...
                parent.Color, ...
                numberOfSubclusters);
            
            childIDs = ...
                zeros(numberOfSubclusters, 1);
            
            for childNumber = 1:numberOfSubclusters
                
                childPixels = ...
                    localPixelIdx( ...
                    selectedLabels == childNumber);
                
                child = struct();
                
                child.ID = ...
                    app.NextClusterID;
                
                childIDs(childNumber) = ...
                    child.ID;
                
                child.Name = ...
                    sprintf( ...
                    '%s.%d', ...
                    parent.Name, ...
                    childNumber);
                
                child.ParentID = ...
                    parent.ID;
                
                child.PixelIdx = ...
                    double(childPixels(:));
                
                child.Class = ...
                    'Unassigned';
                
                child.Active = ...
                    true;
                
                child.MeanSpectrum = ...
                    [];
                
                child.StdSpectrum = ...
                    [];
                
                child.Color = ...
                    childColors(childNumber, :);
                
                child.Metrics = ...
                    struct();
                
                app.Clusters(end + 1) = ...
                    child; %#ok<AGROW>
                
                app.NextClusterID = ...
                    app.NextClusterID + 1;
            end
            
            % =============================================================
            % 13. FINAL PIXEL-INTEGRITY ASSERTION
            % =============================================================
            
            % =============================================================
            % 13. FINAL PIXEL-INTEGRITY ASSERTION
            %
            % Verify that:
            %   1. every parent pixel appears in exactly one child;
            %   2. no child pixels overlap;
            %   3. no parent pixel is lost;
            %   4. no new pixel is introduced.
            % =============================================================
            
            createdPixels = ...
                zeros(0, 1);
            
            for childID = childIDs(:)'
                
                childIndex = ...
                    app.findClusterIndexByID( ...
                    childID);
                
                if isempty(childIndex)
                    
                    % Roll back immediately if a child object cannot be found.
                    previousState = ...
                        app.UndoStack{end};
                    
                    app.UndoStack(end) = [];
                    
                    app.restoreClusterSnapshot( ...
                        previousState);
                    
                    error( ...
                        'A newly created child cluster could not be located.');
                end
                
                currentChildPixels = ...
                    double( ...
                    app.Clusters(childIndex).PixelIdx(:));
                
                createdPixels = ...
                    [ ...
                    createdPixels; ...
                    currentChildPixels]; %#ok<AGROW>
            end
            
            parentPixels = ...
                double( ...
                parent.PixelIdx(:));
            
            % -------------------------------------------------------------
            % Separate checks make the failure reason unambiguous.
            % -------------------------------------------------------------
            
            pixelCountMismatch = ...
                numel(createdPixels) ~= ...
                numel(parentPixels);
            
            duplicateChildPixels = ...
                numel(unique(createdPixels)) ~= ...
                numel(createdPixels);
            
            parentHasDuplicatePixels = ...
                numel(unique(parentPixels)) ~= ...
                numel(parentPixels);
            
            pixelSetMismatch = ...
                ~isequal( ...
                sort(createdPixels), ...
                sort(parentPixels));
            
            if pixelCountMismatch || ...
                    duplicateChildPixels || ...
                    parentHasDuplicatePixels || ...
                    pixelSetMismatch
                
                % Restore the complete cluster state immediately.
                previousState = ...
                    app.UndoStack{end};
                
                app.UndoStack(end) = [];
                
                app.restoreClusterSnapshot( ...
                    previousState);
                
                error( ...
                    sprintf([ ...
                    'The adaptive split failed pixel-integrity QC.\n\n' ...
                    'Parent pixels: %d\n' ...
                    'Created child pixels: %d\n' ...
                    'Unique child pixels: %d\n' ...
                    'Pixel-count mismatch: %d\n' ...
                    'Duplicate child pixels: %d\n' ...
                    'Duplicate parent pixels: %d\n' ...
                    'Pixel-set mismatch: %d\n\n' ...
                    'The split was rolled back automatically.'], ...
                    numel(parentPixels), ...
                    numel(createdPixels), ...
                    numel(unique(createdPixels)), ...
                    pixelCountMismatch, ...
                    duplicateChildPixels, ...
                    parentHasDuplicatePixels, ...
                    pixelSetMismatch));
            end
            
            app.SelectedClusterIDs = ...
                childIDs(:).';
            
            app.ClusterConfirmed = ...
                false;
            
            app.LastSplitStability = struct( ...
                'CreatedAt', ...
                datetime('now'), ...
                'ParentID', ...
                parent.ID, ...
                'ParentName', ...
                parent.Name, ...
                'ParentDepth', ...
                parentDepth, ...
                'Accepted', ...
                true, ...
                'Decision', ...
                decisionStage, ...
                'NumberChildren', ...
                numberOfSubclusters, ...
                'RunsUsed', ...
                numel(finalStability.RunResults), ...
                'OverallMedianARI', ...
                finalStability.OverallMedianARI, ...
                'MedoidMedianARI', ...
                finalStability.MedoidMedianARI, ...
                'SelectedRun', ...
                finalStability.SelectedRun, ...
                'SelectedSeed', ...
                finalStability.SelectedSeed, ...
                'MedianConfidence', ...
                splitQC.MedianConfidence, ...
                'ChildSizes', ...
                splitQC.ChildSizes, ...
                'RunResults', ...
                finalStability.RunResults, ...
                'PairwiseARI', ...
                finalStability.PairwiseARI);
            
            app.refreshClusterState();
            
            % =============================================================
            % 14. REPORT
            % =============================================================
            
            textLines = { ...
                'ADAPTIVE CLUSTER SPLIT COMPLETED'; ...
                '================================================'; ...
                ''; ...
                ['Parent: ' parent.Name]; ...
                sprintf( ...
                'Parent pixels: %d', ...
                numberPixels); ...
                sprintf( ...
                'Parent split depth: %d', ...
                parentDepth); ...
                sprintf( ...
                'Child split depth: %d', ...
                parentDepth + 1); ...
                sprintf( ...
                'PCA dimensions reused: %d', ...
                size(localScores, 2)); ...
                ''; ...
                'STABILITY'; ...
                ['Assessment: ' decisionStage]; ...
                sprintf( ...
                'Runs used: %d', ...
                numel(finalStability.RunResults)); ...
                sprintf( ...
                'Overall median pairwise ARI: %.3f', ...
                finalStability.OverallMedianARI); ...
                sprintf( ...
                'Selected medoid seed: %d', ...
                finalStability.SelectedSeed); ...
                sprintf( ...
                'Median child-assignment confidence: %.3f', ...
                splitQC.MedianConfidence); ...
                ''; ...
                'CHILDREN'};
            
            for childNumber = 1:numberOfSubclusters
                
                childIndex = ...
                    app.findClusterIndexByID( ...
                    childIDs(childNumber));
                
                textLines{end + 1, 1} = ...
                    sprintf( ...
                    '%s | ID=%d | pixels=%d', ...
                    app.Clusters(childIndex).Name, ...
                    childIDs(childNumber), ...
                    splitQC.ChildSizes(childNumber)); %#ok<AGROW>
            end
            
            textLines = ...
                [ ...
                textLines; ...
                { ...
                ''; ...
                'The original parent remains in inactive history.'; ...
                'No PCA model was refitted.'; ...
                'Further splitting is limited by depth, size, stability, and separation.'}];
            
            app.TextArea.Value = ...
                textLines;
            
        end
        
        
        function purityRoot = ...
                addPurityResultsToTree( ...
                app, ...
                root, ...
                activeIndices)
            
            % =============================================================
            % ADD SELECTABLE PURITY RESULTS TO TREE
            %
            % Each active segmentation cluster is partitioned into:
            %
            %   Background:
            %       P(cell) <= PixelBackgroundThreshold
            %
            %   Intermediate:
            %       PixelBackgroundThreshold < P(cell) <
            %       PixelCellThreshold
            %
            %   Cell:
            %       P(cell) >= PixelCellThreshold
            %
            % These are derived spectral groups. They carry PixelIdx and can
            % be selected together with ordinary clusters for comparison.
            %
            % They are deliberately not written into app.Clusters and do not
            % modify ClusterLabelMap.
            % =============================================================
            
            purityRoot = [];
            
            if ~app.PixelAssessmentReady || ...
                    isempty(app.CellMembershipMap) || ...
                    isempty(activeIndices)
                
                return
            end
            
            if ~isequal( ...
                    size(app.CellMembershipMap), ...
                    size(app.ValidMask2D))
                
                return
            end
            
            % =============================================================
            % 1. VISUAL SEPARATOR
            % =============================================================
            
            separatorNode = ...
                uitreenode( ...
                root, ...
                'Text', ...
                '════════════ PURITY RESULTS ════════════', ...
                'NodeData', ...
                struct( ...
                'Type', ...
                'purity_separator'));
            
            try
                
                separatorStyle = ...
                    uistyle( ...
                    'FontWeight', ...
                    'bold', ...
                    'FontColor', ...
                    [0.48 0.22 0.62]);
                
                addStyle( ...
                    app.Tree, ...
                    separatorStyle, ...
                    'node', ...
                    separatorNode);
                
            catch
            end
            
            % =============================================================
            % 2. PURITY ROOT
            % =============================================================
            
            purityRoot = ...
                uitreenode( ...
                root, ...
                'Text', ...
                sprintf( ...
                'Purity composition by cluster (%d)', ...
                numel(activeIndices)), ...
                'NodeData', ...
                struct( ...
                'Type', ...
                'purity_root'));
            
            try
                
                purityRootStyle = ...
                    uistyle( ...
                    'FontWeight', ...
                    'bold', ...
                    'FontColor', ...
                    [0.48 0.22 0.62]);
                
                addStyle( ...
                    app.Tree, ...
                    purityRootStyle, ...
                    'node', ...
                    purityRoot);
                
            catch
            end
            
            % =============================================================
            % 3. ONE PURITY BRANCH PER ACTIVE CLUSTER
            % =============================================================
            
            for clusterIndex = activeIndices(:)'
                
                C = ...
                    app.Clusters(clusterIndex);
                
                clusterPixels = ...
                    unique( ...
                    double(C.PixelIdx(:)), ...
                    'stable');
                
                if isempty(clusterPixels)
                    continue
                end
                
                membershipValues = ...
                    app.CellMembershipMap(clusterPixels);
                
                finiteMembership = ...
                    isfinite(membershipValues);
                
                evaluatedPixels = ...
                    clusterPixels(finiteMembership);
                
                evaluatedMembership = ...
                    membershipValues(finiteMembership);
                
                backgroundPixels = ...
                    evaluatedPixels( ...
                    evaluatedMembership <= ...
                    app.PixelBackgroundThreshold);
                
                intermediatePixels = ...
                    evaluatedPixels( ...
                    evaluatedMembership > ...
                    app.PixelBackgroundThreshold & ...
                    evaluatedMembership < ...
                    app.PixelCellThreshold);
                
                cellPixels = ...
                    evaluatedPixels( ...
                    evaluatedMembership >= ...
                    app.PixelCellThreshold);
                
                unevaluatedPixels = ...
                    clusterPixels(~finiteMembership);
                
                clusterCount = ...
                    numel(clusterPixels);
                
                backgroundFraction = ...
                    numel(backgroundPixels) / ...
                    max(1, clusterCount);
                
                intermediateFraction = ...
                    numel(intermediatePixels) / ...
                    max(1, clusterCount);
                
                cellFraction = ...
                    numel(cellPixels) / ...
                    max(1, clusterCount);
                
                unevaluatedFraction = ...
                    numel(unevaluatedPixels) / ...
                    max(1, clusterCount);
                
                % =========================================================
                % CLUSTER PURITY PARENT
                %
                % This parent is also selectable and represents all pixels
                % of the original segmentation cluster.
                % =========================================================
                
                purityClusterNode = ...
                    uitreenode( ...
                    purityRoot, ...
                    'Text', ...
                    sprintf( ...
                    ['%s | n=%d | BG %.1f%% | ' ...
                    'Intermediate %.1f%% | Cell %.1f%%'], ...
                    C.Name, ...
                    clusterCount, ...
                    100 * backgroundFraction, ...
                    100 * intermediateFraction, ...
                    100 * cellFraction), ...
                    'NodeData', ...
                    struct( ...
                    'Type', ...
                    'purity_cluster', ...
                    'Name', ...
                    [C.Name ' — all purity-evaluated pixels'], ...
                    'ClusterID', ...
                    C.ID, ...
                    'PixelIdx', ...
                    double(evaluatedPixels(:)), ...
                    'Color', ...
                    double(C.Color(:).'), ...
                    'PurityClass', ...
                    'All'));
                
                try
                    
                    parentStyle = ...
                        uistyle( ...
                        'FontWeight', ...
                        'bold', ...
                        'FontColor', ...
                        C.Color);
                    
                    addStyle( ...
                        app.Tree, ...
                        parentStyle, ...
                        'node', ...
                        purityClusterNode);
                    
                catch
                end
                
                % =========================================================
                % BACKGROUND COMPONENT
                % =========================================================
                
                if ~isempty(backgroundPixels)
                    
                    backgroundNode = ...
                        uitreenode( ...
                        purityClusterNode, ...
                        'Text', ...
                        sprintf( ...
                        'Background | n=%d | %.2f%%', ...
                        numel(backgroundPixels), ...
                        100 * backgroundFraction), ...
                        'NodeData', ...
                        struct( ...
                        'Type', ...
                        'purity_component', ...
                        'Name', ...
                        [C.Name ' — Background'], ...
                        'ClusterID', ...
                        C.ID, ...
                        'PurityClass', ...
                        'Background', ...
                        'PixelIdx', ...
                        double(backgroundPixels(:)), ...
                        'Color', ...
                        app.PurityBackgroundColor, ...
                        'PixelFraction', ...
                        backgroundFraction));
                    
                    try
                        
                        backgroundStyle = ...
                            uistyle( ...
                            'FontWeight', ...
                            'bold', ...
                            'FontColor', ...
                            app.PurityBackgroundColor);
                        
                        addStyle( ...
                            app.Tree, ...
                            backgroundStyle, ...
                            'node', ...
                            backgroundNode);
                        
                    catch
                    end
                end
                
                % =========================================================
                % INTERMEDIATE COMPONENT
                % =========================================================
                
                if ~isempty(intermediatePixels)
                    
                    intermediateNode = ...
                        uitreenode( ...
                        purityClusterNode, ...
                        'Text', ...
                        sprintf( ...
                        'Intermediate | n=%d | %.2f%%', ...
                        numel(intermediatePixels), ...
                        100 * intermediateFraction), ...
                        'NodeData', ...
                        struct( ...
                        'Type', ...
                        'purity_component', ...
                        'Name', ...
                        [C.Name ' — Intermediate'], ...
                        'ClusterID', ...
                        C.ID, ...
                        'PurityClass', ...
                        'Intermediate', ...
                        'PixelIdx', ...
                        double(intermediatePixels(:)), ...
                        'Color', ...
                        app.PurityIntermediateColor, ...
                        'PixelFraction', ...
                        intermediateFraction));
                    
                    try
                        
                        intermediateStyle = ...
                            uistyle( ...
                            'FontWeight', ...
                            'bold', ...
                            'FontColor', ...
                            app.PurityIntermediateColor);
                        
                        addStyle( ...
                            app.Tree, ...
                            intermediateStyle, ...
                            'node', ...
                            intermediateNode);
                        
                    catch
                    end
                end
                
                % =========================================================
                % CELL COMPONENT
                % =========================================================
                
                if ~isempty(cellPixels)
                    
                    cellNode = ...
                        uitreenode( ...
                        purityClusterNode, ...
                        'Text', ...
                        sprintf( ...
                        'Cell | n=%d | %.2f%%', ...
                        numel(cellPixels), ...
                        100 * cellFraction), ...
                        'NodeData', ...
                        struct( ...
                        'Type', ...
                        'purity_component', ...
                        'Name', ...
                        [C.Name ' — Cell'], ...
                        'ClusterID', ...
                        C.ID, ...
                        'PurityClass', ...
                        'Cell', ...
                        'PixelIdx', ...
                        double(cellPixels(:)), ...
                        'Color', ...
                        app.PurityCellColor, ...
                        'PixelFraction', ...
                        cellFraction));
                    
                    try
                        
                        cellStyle = ...
                            uistyle( ...
                            'FontWeight', ...
                            'bold', ...
                            'FontColor', ...
                            app.PurityCellColor);
                        
                        addStyle( ...
                            app.Tree, ...
                            cellStyle, ...
                            'node', ...
                            cellNode);
                        
                    catch
                    end
                end
                
                % =========================================================
                % UNEVALUATED COMPONENT
                %
                % Normally empty. It is retained explicitly when membership
                % prediction is unavailable for some cluster pixels.
                % =========================================================
                
                if ~isempty(unevaluatedPixels)
                    
                    unevaluatedNode = ...
                        uitreenode( ...
                        purityClusterNode, ...
                        'Text', ...
                        sprintf( ...
                        'Not evaluated | n=%d | %.2f%%', ...
                        numel(unevaluatedPixels), ...
                        100 * unevaluatedFraction), ...
                        'NodeData', ...
                        struct( ...
                        'Type', ...
                        'purity_component', ...
                        'Name', ...
                        [C.Name ' — Not evaluated'], ...
                        'ClusterID', ...
                        C.ID, ...
                        'PurityClass', ...
                        'Not evaluated', ...
                        'PixelIdx', ...
                        double(unevaluatedPixels(:)), ...
                        'Color', ...
                        [0.50 0.50 0.50], ...
                        'PixelFraction', ...
                        unevaluatedFraction));
                    
                    try
                        
                        unevaluatedStyle = ...
                            uistyle( ...
                            'FontAngle', ...
                            'italic', ...
                            'FontColor', ...
                            [0.50 0.50 0.50]);
                        
                        addStyle( ...
                            app.Tree, ...
                            unevaluatedStyle, ...
                            'node', ...
                            unevaluatedNode);
                        
                    catch
                    end
                end
                
                try
                    expand(purityClusterNode);
                catch
                end
            end
            
            try
                expand(purityRoot);
            catch
            end
            
        end
        
        
        function groups = ...
                getSelectedSpectralGroupsFromTree(app)
            
            % =============================================================
            % RETURN EVERY SELECTED NODE THAT REPRESENTS A PIXEL GROUP
            %
            % Supported:
            %   - normal segmentation cluster;
            %   - purity-cluster parent;
            %   - Background purity component;
            %   - Intermediate purity component;
            %   - Cell purity component.
            %
            % A selected group is treated as one independent spectral group.
            % =============================================================
            
            groups = struct( ...
                'Name', {}, ...
                'PixelIdx', {}, ...
                'Color', {}, ...
                'Type', {}, ...
                'ClusterID', {}, ...
                'PurityClass', {});
            
            selectedNodes = ...
                app.Tree.SelectedNodes;
            
            if isempty(selectedNodes)
                return
            end
            
            for nodeNumber = 1:numel(selectedNodes)
                
                data = ...
                    selectedNodes(nodeNumber).NodeData;
                
                if isempty(data) || ...
                        ~isstruct(data) || ...
                        ~isfield(data, 'PixelIdx')
                    
                    continue
                end
                
                pixelIdx = ...
                    unique( ...
                    double(data.PixelIdx(:)), ...
                    'stable');
                
                if isempty(pixelIdx)
                    continue
                end
                
                group = struct();
                
                if isfield(data, 'Name') && ...
                        ~isempty(data.Name)
                    
                    group.Name = ...
                        char(string(data.Name));
                    
                else
                    
                    group.Name = ...
                        char(string(selectedNodes(nodeNumber).Text));
                end
                
                group.PixelIdx = ...
                    pixelIdx;
                
                if isfield(data, 'Color') && ...
                        numel(data.Color) == 3
                    
                    group.Color = ...
                        max( ...
                        0, ...
                        min( ...
                        1, ...
                        double(data.Color(:).')));
                    
                else
                    
                    group.Color = ...
                        [0.30 0.30 0.30];
                end
                
                if isfield(data, 'Type')
                    
                    group.Type = ...
                        char(string(data.Type));
                    
                else
                    
                    group.Type = ...
                        'spectral_group';
                end
                
                if isfield(data, 'ClusterID') && ...
                        isfinite(double(data.ClusterID))
                    
                    group.ClusterID = ...
                        double(data.ClusterID);
                    
                elseif isfield(data, 'ID') && ...
                        isfinite(double(data.ID))
                    
                    group.ClusterID = ...
                        double(data.ID);
                    
                else
                    
                    group.ClusterID = ...
                        NaN;
                end
                
                if isfield(data, 'PurityClass')
                    
                    group.PurityClass = ...
                        char(string(data.PurityClass));
                    
                else
                    
                    group.PurityClass = ...
                        '';
                end
                
                groups(end + 1) = ...
                    group; %#ok<AGROW>
            end
            
        end
        
        
        function groups = buildEqualScanGroups(app)
            
            if isempty(app.SingleExtractSamples)
                
                error( ...
                    'single_extract:NoLoadedSamples', ...
                    'No sample has been loaded.');
            end
            
            numberSamples = ...
                numel(app.SingleExtractSamples);
            
            groups = ...
                struct( ...
                'Name', {}, ...
                'SampleIndices', {});
            
            for sampleNumber = 1:numberSamples
                
                G = struct();
                
                G.Name = ...
                    app.SingleExtractSamples(sampleNumber).Name;
                
                G.SampleIndices = ...
                    sampleNumber;
                
                groups(end + 1) = ...
                    G; %#ok<AGROW>
            end
            
        end
        
        
        function writeSingleExtractGroupProvenance( ...
                app, ...
                txtPath, ...
                groupResult, ...
                ruleName, ...
                resolutionMode)
            
            fileID = ...
                fopen( ...
                txtPath, ...
                'w');
            
            if fileID < 0
                
                error( ...
                    'single_extract:OutputTXTFailed', ...
                    sprintf( ...
                    'Could not create provenance file:\n%s', ...
                    txtPath));
            end
            
            cleanupObject = ...
                onCleanup( ...
                @() fclose(fileID)); %#ok<NASGU>
            
            fprintf( ...
                fileID, ...
                'RAMANCUBE SINGLE SPECTRA DATASET PROVENANCE\n');
            
            fprintf( ...
                fileID, ...
                '============================================================\n\n');
            
            fprintf( ...
                fileID, ...
                'Created: %s\n', ...
                char( ...
                datetime( ...
                'now', ...
                'Format', ...
                'yyyy-MM-dd HH:mm:ss.SSS')));
            
            fprintf( ...
                fileID, ...
                'Group name: %s\n', ...
                groupResult.Name);
            
            fprintf( ...
                fileID, ...
                'Rule: %s\n', ...
                ruleName);
            
            fprintf( ...
                fileID, ...
                'Resolution mode: %s\n', ...
                resolutionMode);
            
            fprintf( ...
                fileID, ...
                'Target pixels per representative: %d\n', ...
                groupResult.TargetPixelsPerMean);
            
            fprintf( ...
                fileID, ...
                'Minimum representatives setting: %d\n', ...
                app.SingleExtractMinimumRepresentatives);
            
            fprintf( ...
                fileID, ...
                'Random seed: %d\n', ...
                app.SingleExtractRandomSeed);
            
            fprintf( ...
                fileID, ...
                'Wavenumber channels: %d\n', ...
                numel(groupResult.Wavenumber));
            
            fprintf( ...
                fileID, ...
                'Total representative spectra: %d\n\n', ...
                groupResult.NumberRepresentatives);
            
            fprintf( ...
                fileID, ...
                ['IMPORTANT: representative spectra from different scans were ' ...
                'never mixed into one mean.\n']);
            
            fprintf( ...
                fileID, ...
                ['Every representative mean was calculated only from pixels ' ...
                'belonging to one source scan.\n']);
            
            fprintf( ...
                fileID, ...
                ['All cluster pixels were retained by spatial partitioning; ' ...
                'spectral QC was descriptive and did not silently delete pixels.\n\n']);
            
            % =============================================================
            % SAMPLE LEVEL
            % =============================================================
            
            fprintf( ...
                fileID, ...
                'SAMPLE-LEVEL RESULTS\n');
            
            fprintf( ...
                fileID, ...
                '------------------------------------------------------------\n');
            
            for sampleNumber = 1:numel(groupResult.SampleResults)
                
                R = ...
                    groupResult.SampleResults(sampleNumber);
                
                sourceSample = ...
                    app.SingleExtractSamples( ...
                    R.SampleIndex);
                
                fprintf( ...
                    fileID, ...
                    '\nSample: %s\n', ...
                    R.SampleName);
                
                fprintf( ...
                    fileID, ...
                    'Source cluster MAT: %s\n', ...
                    sourceSample.ClusterMATPath);
                
                fprintf( ...
                    fileID, ...
                    'Latest extraction run: %s\n', ...
                    sourceSample.RunFolder);
                
                fprintf( ...
                    fileID, ...
                    'Cluster pixels: %d\n', ...
                    sourceSample.PixelCount);
                
                fprintf( ...
                    fileID, ...
                    'Representative spectra generated: %d\n', ...
                    R.NumberRepresentatives);
                
                fprintf( ...
                    fileID, ...
                    'Pixels per representative: %s\n', ...
                    mat2str( ...
                    R.PixelsPerRepresentative(:).'));
                
                fprintf( ...
                    fileID, ...
                    'Spatial islands represented: %d\n', ...
                    numel(unique(R.IslandID)));
                
                fprintf( ...
                    fileID, ...
                    'All pixels preserved: %d\n', ...
                    R.AllPixelsPreserved);
                
                fprintf( ...
                    fileID, ...
                    'Spectral QC flagged representatives: %d / %d\n', ...
                    R.SpectralQC.FlaggedCount, ...
                    R.NumberRepresentatives);
                
                fprintf( ...
                    fileID, ...
                    'Spectral QC robust median dispersion: %.8g\n', ...
                    R.SpectralQC.RobustMedian);
                
                fprintf( ...
                    fileID, ...
                    'Spectral QC robust MAD: %.8g\n', ...
                    R.SpectralQC.RobustMAD);
                
                fprintf( ...
                    fileID, ...
                    'Spectral QC flag threshold: %.8g\n', ...
                    R.SpectralQC.FlagThreshold);
                
                % ---------------------------------------------------------
                % REPRESENTATIVE DETAIL
                % ---------------------------------------------------------
                
                fprintf( ...
                    fileID, ...
                    '\nRepresentative detail:\n');
                
                for representativeNumber = ...
                        1:R.NumberRepresentatives
                    
                    pixelIdx = ...
                        R.SpatialGroups{representativeNumber};
                    
                    [rowCoordinates, columnCoordinates] = ...
                        ind2sub( ...
                        size(sourceSample.ClusterPackage.Map.ClusterMask2D), ...
                        pixelIdx);
                    
                    centroidRow = ...
                        mean( ...
                        rowCoordinates, ...
                        'omitnan');
                    
                    centroidColumn = ...
                        mean( ...
                        columnCoordinates, ...
                        'omitnan');
                    
                    fprintf( ...
                        fileID, ...
                        [ ...
                        '  Rep %04d | pixels=%d | island=%d | ' ...
                        'centroid=(%.3f, %.3f) | dispersion=%.8g | QC=%s\n'], ...
                        representativeNumber, ...
                        numel(pixelIdx), ...
                        R.IslandID(representativeNumber), ...
                        centroidRow, ...
                        centroidColumn, ...
                        R.SpectralQC.Dispersion(representativeNumber), ...
                        app.singleExtractPassFlagText( ...
                        R.SpectralQC.Flagged(representativeNumber)));
                end
            end
            
        end
        
        
        function outputInfo = saveSingleExtractGroupDataset( ...
                app, ...
                groupResult, ...
                ruleName, ...
                resolutionMode)
            
            safeGroupName = ...
                app.makeSafeFileName( ...
                groupResult.Name);
            
            if isempty(safeGroupName)
                
                error( ...
                    'single_extract:InvalidOutputGroupName', ...
                    'The group name cannot be converted to a valid folder name.');
            end
            
            outputFolder = ...
                fullfile( ...
                app.SingleExtractOutputRoot, ...
                safeGroupName);
            
            if ~exist(outputFolder, 'dir')
                
                mkdir(outputFolder);
            end
            
            matPath = ...
                fullfile( ...
                outputFolder, ...
                [safeGroupName '.mat']);
            
            txtPath = ...
                fullfile( ...
                outputFolder, ...
                [safeGroupName '_provenance.txt']);
            
            % =============================================================
            % MINIMAL MAT FILE
            %
            % Only these two analytical variables are saved.
            % =============================================================
            
            Wavenumber = ...
                double( ...
                groupResult.Wavenumber(:));
            
            MeanSpectra = ...
                double( ...
                groupResult.MeanSpectra);
            
            save( ...
                matPath, ...
                'Wavenumber', ...
                'MeanSpectra', ...
                '-v7.3');
            
            % =============================================================
            % PROVENANCE SEPARATE FROM ANALYTICAL MAT
            % =============================================================
            
            app.writeSingleExtractGroupProvenance( ...
                txtPath, ...
                groupResult, ...
                ruleName, ...
                resolutionMode);
            
            outputInfo = struct();
            
            outputInfo.GroupName = ...
                groupResult.Name;
            
            outputInfo.OutputFolder = ...
                outputFolder;
            
            outputInfo.MATPath = ...
                matPath;
            
            outputInfo.ProvenancePath = ...
                txtPath;
            
            outputInfo.NumberRepresentatives = ...
                groupResult.NumberRepresentatives;
            
        end
        
        
        function groups = requestSingleExtractGroups(app)
            
            groups = ...
                struct( ...
                'Name', {}, ...
                'SampleIndices', {});
            
            if isempty(app.SingleExtractSamples)
                
                uialert( ...
                    app.UIFigure, ...
                    'Load cluster samples first.', ...
                    'No loaded sample');
                
                return
            end
            
            numberSamples = ...
                numel(app.SingleExtractSamples);
            
            sampleNames = ...
                {app.SingleExtractSamples.Name};
            
            unassignedIndices = ...
                1:numberSamples;
            
            groupNumber = ...
                1;
            
            while ~isempty(unassignedIndices)
                
                % =============================================================
                % 1. GROUP NAME
                % =============================================================
                
                defaultName = ...
                    sprintf( ...
                    'Group_%d', ...
                    groupNumber);
                
                answer = ...
                    inputdlg( ...
                    { ...
                    sprintf( ...
                    'Name for group %d:', ...
                    groupNumber)}, ...
                    'Define sample group', ...
                    [1 55], ...
                    {defaultName});
                
                if isempty(answer)
                    
                    groups = ...
                        struct( ...
                        'Name', {}, ...
                        'SampleIndices', {});
                    
                    return
                end
                
                groupName = ...
                    strtrim( ...
                    char(string(answer{1})));
                
                if isempty(groupName)
                    
                    uialert( ...
                        app.UIFigure, ...
                        'Group name cannot be empty.', ...
                        'Invalid group name');
                    
                    continue
                end
                
                existingNames = ...
                    {groups.Name};
                
                if any(strcmpi(existingNames, groupName))
                    
                    uialert( ...
                        app.UIFigure, ...
                        sprintf( ...
                        'Group "%s" already exists.', ...
                        groupName), ...
                        'Duplicate group name');
                    
                    continue
                end
                
                % =============================================================
                % 2. SELECT SAMPLES FOR THIS GROUP
                % =============================================================
                
                availableNames = ...
                    sampleNames(unassignedIndices);
                
                [localSelection, ok] = ...
                    listdlg( ...
                    'PromptString', ...
                    { ...
                    ['Select scans belonging to "' ...
                    groupName '":']; ...
                    ''; ...
                    'Each scan can belong to one group only.'}, ...
                    'SelectionMode', ...
                    'multiple', ...
                    'ListString', ...
                    availableNames, ...
                    'ListSize', ...
                    [440 420]);
                
                if ~ok
                    
                    groups = ...
                        struct( ...
                        'Name', {}, ...
                        'SampleIndices', {});
                    
                    return
                end
                
                if isempty(localSelection)
                    
                    uialert( ...
                        app.UIFigure, ...
                        'Select at least one sample for this group.', ...
                        'Empty group');
                    
                    continue
                end
                
                selectedSampleIndices = ...
                    unassignedIndices(localSelection);
                
                % =============================================================
                % 3. STORE GROUP
                % =============================================================
                
                G = struct();
                
                G.Name = ...
                    groupName;
                
                G.SampleIndices = ...
                    selectedSampleIndices(:).';
                
                groups(end + 1) = ...
                    G; %#ok<AGROW>
                
                % Remove assigned samples.
                unassignedIndices(localSelection) = ...
                    [];
                
                groupNumber = ...
                    groupNumber + 1;
                
                % =============================================================
                % 4. IF SAMPLES REMAIN, ASK WHETHER TO CONTINUE
                % =============================================================
                
                if ~isempty(unassignedIndices)
                    
                    remainingNames = ...
                        sampleNames(unassignedIndices);
                    
                    remainingText = ...
                        strjoin( ...
                        remainingNames, ...
                        ', ');
                    
                    choice = ...
                        uiconfirm( ...
                        app.UIFigure, ...
                        { ...
                        sprintf( ...
                        'Group "%s" was defined.', ...
                        groupName); ...
                        ''; ...
                        sprintf( ...
                        'Unassigned samples remaining: %d', ...
                        numel(unassignedIndices)); ...
                        remainingText; ...
                        ''; ...
                        'Create the next group?'}, ...
                        'Continue group definition', ...
                        'Options', ...
                        {'Create next group', 'Cancel all'}, ...
                        'DefaultOption', ...
                        'Create next group', ...
                        'CancelOption', ...
                        'Cancel all');
                    
                    if ~strcmp( ...
                            choice, ...
                            'Create next group')
                        
                        groups = ...
                            struct( ...
                            'Name', {}, ...
                            'SampleIndices', {});
                        
                        return
                    end
                end
            end
            
        end
        
        
        function outputPath = showSingleExtractQCSummary( ...
                app, ...
                results, ...
                targetPixels, ...
                resolutionMode)
            
            if isempty(results)
                outputPath = '';
                return
            end
            
            numberScans = ...
                numel(results);
            
            rawNames = ...
                string({results.SampleName});
            
            scanNames = ...
                app.makeDisplayLabels( ...
                rawNames);
            
            inputPixels = ...
                double([results.InputPixelCount]);
            
            representedPixels = ...
                double([results.RepresentedPixelCount]);
            
            totalRepresentatives = ...
                double([results.NumberRepresentatives]);
            
            normalRepresentatives = ...
                zeros(1, numberScans);
            
            undersizedCounts = ...
                double([results.UndersizedCount]);
            
            singletonCounts = ...
                double([results.SingletonCount]);
            
            oversizedCounts = ...
                double([results.OversizedCount]);
            
            spectralFlagCounts = ...
                zeros(1, numberScans);
            
            for i = 1:numberScans
                
                normalRepresentatives(i) = ...
                    nnz(string(results(i).SpatialStatus(:)) == "Normal");
                
                spectralFlagCounts(i) = ...
                    results(i).SpectralQC.FlaggedCount;
            end
            
            qcFigure = ...
                figure( ...
                'Name', ...
                'Representative extraction summary', ...
                'Color', ...
                'w', ...
                'Position', ...
                [100 80 1500 850]);
            
            layout = ...
                tiledlayout( ...
                qcFigure, ...
                2, ...
                1, ...
                'TileSpacing', ...
                'compact', ...
                'Padding', ...
                'compact');
            
            % =============================================================
            % PANEL 1
            % PIXEL CONSERVATION
            % =============================================================
            
            ax1 = ...
                nexttile(layout);
            
            conservationMatrix = ...
                [inputPixels(:), representedPixels(:)];
            
            bar( ...
                ax1, ...
                conservationMatrix, ...
                'grouped');
            
            grid(ax1, 'on');
            box(ax1, 'on');
            
            ax1.XTick = ...
                1:numberScans;
            
            ax1.XTickLabel = ...
                scanNames;
            
            
            ax1.XTickLabelRotation = ...
                35;
            
            ax1.TickLabelInterpreter = ...
                'none';
            
            ylabel(ax1, 'Pixel count');
            title(ax1, 'Pixel conservation by scan');
            
            legend( ...
                ax1, ...
                {'Input cluster pixels', 'Represented pixels'}, ...
                'Location', ...
                'best', ...
                'Interpreter', ...
                'none');
            
            % =============================================================
            % PANEL 2
            % TOTAL / NORMAL / QC ERRORS
            % =============================================================
            
            ax2 = ...
                nexttile(layout);
            
            summaryMatrix = ...
                [ ...
                totalRepresentatives(:), ...
                normalRepresentatives(:), ...
                undersizedCounts(:), ...
                singletonCounts(:), ...
                oversizedCounts(:)];
            
            bar( ...
                ax2, ...
                summaryMatrix, ...
                'grouped');
            
            grid(ax2, 'on');
            box(ax2, 'on');
            
            ax2.XTick = ...
                1:numberScans;
            
            ax2.XTickLabel = ...
                scanNames;
            
            ax2.XTickLabelRotation = ...
                35;
            
            ax2.TickLabelInterpreter = ...
                'none';
            
            ylabel(ax2, 'Representative count');
            
            title( ...
                ax2, ...
                sprintf( ...
                ['Representative outcome by scan | ' ...
                'Automatic target support = %d pixels'], ...
                targetPixels), ...
                'Interpreter', ...
                'none');
            
            legend( ...
                ax2, ...
                { ...
                'Total representatives', ...
                'Normal (saved to MAT)', ...
                'Undersized', ...
                'Singleton', ...
                'Oversized'}, ...
                'Location', ...
                'best', ...
                'Interpreter', ...
                'none');
            
            title( ...
                layout, ...
                { ...
                'Scan-wise representative extraction summary'; ...
                sprintf( ...
                '%s | only Normal representatives are exported to analytical MAT', ...
                resolutionMode)}, ...
                'Interpreter', ...
                'none');
            
            qcFolder = ...
                fullfile( ...
                app.SingleExtractOutputRoot, ...
                '_extraction_QC');
            
            if ~exist(qcFolder, 'dir')
                mkdir(qcFolder);
            end
            
            timestamp = ...
                char(datetime( ...
                'now', ...
                'Format', ...
                'yyyyMMdd_HHmmss_SSS'));
            
            outputPath = ...
                fullfile( ...
                qcFolder, ...
                ['scanwise_extraction_summary_' timestamp '.png']);
            
            exportgraphics( ...
                qcFigure, ...
                outputPath, ...
                'Resolution', ...
                300);
            
        end
        
        

        function generateSingleSpectraDatasetWorkflow(app)
            
            % =============================================================
            % 1. VALIDATE LOADED INPUT
            % =============================================================
            
            if isempty(app.SingleExtractSamples) || ...
                    ~app.SingleExtractLoaded
                
                uialert( ...
                    app.UIFigure, ...
                    'Load cluster packages first.', ...
                    'No loaded sample');
                
                return
            end
            
            numberSamples = ...
                numel(app.SingleExtractSamples);
            
            % =============================================================
            % 2. CHOOSE COMMON REPRESENTATIVE SUPPORT
            %
            % The target is common across the currently loaded batch.
            % Every scan is still partitioned and saved independently.
            % =============================================================
            
            [targetPixels, resolutionMode, accepted] = ...
                app.requestSingleExtractResolution();
            
            if ~accepted
                return
            end
            
            % =============================================================
            % 3. PRE-GENERATION SUMMARY
            % =============================================================
            
            pixelCounts = ...
                double( ...
                [app.SingleExtractSamples.PixelCount]);
            
            smallestPixelCount = ...
                min(pixelCounts);
            
            approximateMinimumReps = ...
                round( ...
                smallestPixelCount / ...
                max(1, targetPixels));
            
            confirmationLines = { ...
                'Generate scan-wise representative datasets?'; ...
                ''; ...
                sprintf( ...
                'Loaded scans: %d', ...
                numberSamples); ...
                ['Resolution mode: ' resolutionMode]; ...
                sprintf( ...
                'Common target pixels / representative: %d', ...
                targetPixels); ...
                sprintf( ...
                'Smallest loaded scan: %d pixels', ...
                smallestPixelCount); ...
                sprintf( ...
                'Approximate representatives in smallest scan: %d', ...
                approximateMinimumReps); ...
                ''; ...
                'Each scan will be processed independently.'; ...
                'No experimental groups will be created here.'; ...
                'All source pixels remain represented in QC/provenance.'; ...
                ''; ...
                ['Only representatives classified as Normal spatial support ' ...
                'will be exported to the analytical MAT files.']; ...
                ''; ...
                ['Undersized, Singleton and Oversized representatives remain ' ...
                'documented in provenance and membership files.']};
            
            choice = ...
                uiconfirm( ...
                app.UIFigure, ...
                confirmationLines, ...
                'Generate representative spectra', ...
                'Options', ...
                {'Generate', 'Cancel'}, ...
                'DefaultOption', ...
                'Generate', ...
                'CancelOption', ...
                'Cancel');
            
            if ~strcmp(choice, 'Generate')
                return
            end
            
            % =============================================================
            % 4. DETERMINISTIC EXECUTION
            % =============================================================
            
            previousRNG = ...
                rng;
            
            cleanupRNG = ...
                onCleanup( ...
                @() rng(previousRNG)); %#ok<NASGU>
            
            rng( ...
                app.SingleExtractRandomSeed, ...
                'twister');
            
            % =============================================================
            % 5. LOCK GENERATE BUTTON DURING PROCESSING
            % =============================================================
            
            app.GeneratesinglespectradatasetButton.Enable = ...
                'off';
            
            cleanupButton = ...
                onCleanup( ...
                @() set( ...
                app.GeneratesinglespectradatasetButton, ...
                'Enable', ...
                'on')); %#ok<NASGU>
            
            % =============================================================
            % 6. PROCESS EVERY SCAN INDEPENDENTLY
            % =============================================================
            
            results = ...
                struct([]);
            
            outputInfo = ...
                struct([]);
            
            for sampleNumber = 1:numberSamples
                
                S = ...
                    app.SingleExtractSamples(sampleNumber);
                
                app.TextArea_2.Value = { ...
                    'GENERATING REPRESENTATIVE SPECTRA'; ...
                    '================================================'; ...
                    ''; ...
                    sprintf( ...
                    'Scan %d / %d', ...
                    sampleNumber, ...
                    numberSamples); ...
                    ['Name: ' S.Name]; ...
                    sprintf( ...
                    'Input cluster pixels: %d', ...
                    S.PixelCount); ...
                    sprintf( ...
                    'Target pixels / representative: %d', ...
                    targetPixels); ...
                    ''; ...
                    'Building connected spatial representatives...'; ...
                    'Calculating representative mean spectra...'; ...
                    'Running extraction QC...'};
                
                drawnow;
                
                % ---------------------------------------------------------
                % Generate ALL representatives.
                %
                % This result still contains:
                %   Normal
                %   Undersized
                %   Singleton
                %   Oversized
                %
                % No pixel is removed here.
                % ---------------------------------------------------------
                
                currentResult = ...
                    app.generateRepresentativesForOneSample( ...
                    sampleNumber, ...
                    targetPixels);
                
                % ---------------------------------------------------------
                % Save analytical dataset.
                %
                % saveSingleExtractScanDataset is responsible for exporting
                % ONLY SpatialStatus == "Normal" into MeanSpectra.
                %
                % Full result membership remains in TXT provenance.
                % ---------------------------------------------------------
                
                currentOutput = ...
                    app.saveSingleExtractScanDataset( ...
                    currentResult, ...
                    resolutionMode);
                
                if isempty(results)
                    
                    results = ...
                        currentResult;
                    
                    outputInfo = ...
                        currentOutput;
                    
                else
                    
                    results(end + 1) = ...
                        currentResult; %#ok<AGROW>
                    
                    outputInfo(end + 1) = ...
                        currentOutput; %#ok<AGROW>
                end
            end
            
            % =============================================================
            % 7. STORE LAST RESULTS IN APP STATE
            % =============================================================
            
            app.SingleExtractLastResults = ...
                results;
            
            app.SingleExtractLastOutputInfo = ...
                outputInfo;
            
            % Legacy Equal-group state is intentionally unused.
            app.SingleExtractGroups = ...
                app.SingleExtractGroups([]);
            
            
            % =============================================================
            % SAVE SIMPLE EXTRACTION AUDIT MAT FOR POSTER ANALYSIS
            % =============================================================
            
            Audit = struct();
            
            Audit.CreatedAt = ...
                datetime('now');
            
            Audit.Results = ...
                results;
            
            Audit.OutputInfo = ...
                outputInfo;
            
            Audit.NumberClusters = ...
                numel(results);
            
            
            % -------------------------------------------------------------
            % Simple summary table
            % -------------------------------------------------------------
            
            nClusters = ...
                numel(results);
            
            Cluster = ...
                (1:nClusters).';
            
            ClusterName = ...
                strings(nClusters, 1);
            
            InputPixels = ...
                zeros(nClusters, 1);
            
            ExtractedSpectra = ...
                zeros(nClusters, 1);
            
            Normal = ...
                zeros(nClusters, 1);
            
            Undersized = ...
                zeros(nClusters, 1);
            
            Singleton = ...
                zeros(nClusters, 1);
            
            Oversized = ...
                zeros(nClusters, 1);
            
            SpectralQCFlagged = ...
                zeros(nClusters, 1);
            
            
            for i = 1:nClusters
                
                R = ...
                    results(i);
                
                ClusterName(i) = ...
                    string(R.SampleName);
                
                InputPixels(i) = ...
                    R.InputPixelCount;
                
                ExtractedSpectra(i) = ...
                    R.NumberRepresentatives;
                
                Normal(i) = ...
                    R.NormalCount;
                
                Undersized(i) = ...
                    R.UndersizedCount;
                
                Singleton(i) = ...
                    R.SingletonCount;
                
                Oversized(i) = ...
                    R.OversizedCount;
                
                SpectralQCFlagged(i) = ...
                    R.SpectralQC.FlaggedCount;
                
            end
            
            
            Audit.Summary = ...
                table( ...
                Cluster, ...
                ClusterName, ...
                InputPixels, ...
                ExtractedSpectra, ...
                Normal, ...
                Undersized, ...
                Singleton, ...
                Oversized, ...
                SpectralQCFlagged);
            
            
            % -------------------------------------------------------------
            % Fixed poster output folder
            % -------------------------------------------------------------
            
            posterFolder = ...
                'D:\PROJECTS\poster';
            
            if ~isfolder(posterFolder)
                mkdir(posterFolder);
            end
            
            
            auditPath = ...
                fullfile( ...
                posterFolder, ...
                'single_extract_audit.mat');
            
            
            save( ...
                auditPath, ...
                'Audit', ...
                '-v7.3');
            
            
            % =============================================================
            % 8. EXTRACTION QC SUMMARY FIGURE
            %
            % This figure shows:
            %
            %   input / represented pixels
            %   total representatives
            %   Normal analytical representatives
            %   undersized / singleton / oversized QC outcomes
            % =============================================================
            
            qcFigurePath = ...
                app.showSingleExtractQCSummary( ...
                results, ...
                targetPixels, ...
                resolutionMode);
            
            % =============================================================
            % 9. EXPORT MAP FIGURES
            %
            % One independent map figure is generated for each scan.
            % =============================================================
            
            mapFigurePaths = ...
                app.exportSingleExtractMaps( ...
                results);
            
            % =============================================================
            % 10. BATCH COUNTS
            % =============================================================
            
            totalInputPixels = ...
                sum( ...
                [results.InputPixelCount]);
            
            totalRepresentedPixels = ...
                sum( ...
                [results.RepresentedPixelCount]);
            
            totalGeneratedRepresentatives = ...
                sum( ...
                [results.NumberRepresentatives]);
            
            totalNormalRepresentatives = ...
                sum( ...
                [results.NormalCount]);
            
            totalUndersized = ...
                sum( ...
                [results.UndersizedCount]);
            
            totalSingleton = ...
                sum( ...
                [results.SingletonCount]);
            
            totalOversized = ...
                sum( ...
                [results.OversizedCount]);
            
            totalExcludedFromMAT = ...
                totalGeneratedRepresentatives - ...
                totalNormalRepresentatives;
            
            totalSpectralFlagged = ...
                sum( ...
                arrayfun( ...
                @(R) R.SpectralQC.FlaggedCount, ...
                results));
            
            % =============================================================
            % 11. BUILD FINAL REPORT
            % =============================================================
            
            reportLines = { ...
                'SCAN-WISE EXTRACTION COMPLETED'; ...
                '================================================'; ...
                ''; ...
                sprintf( ...
                'Scans processed: %d', ...
                numberSamples); ...
                ['Resolution mode: ' resolutionMode]; ...
                sprintf( ...
                'Target pixels / representative: %d', ...
                targetPixels); ...
                ''; ...
                'BATCH PIXEL CONSERVATION'; ...
                sprintf( ...
                'Input cluster pixels: %d', ...
                totalInputPixels); ...
                sprintf( ...
                'Represented pixels: %d', ...
                totalRepresentedPixels); ...
                sprintf( ...
                'Pixel conservation: %.2f%%', ...
                100 * ...
                totalRepresentedPixels / ...
                max(1, totalInputPixels)); ...
                ''; ...
                'REPRESENTATIVE OUTCOME'; ...
                sprintf( ...
                'Total representatives generated: %d', ...
                totalGeneratedRepresentatives); ...
                sprintf( ...
                'Normal representatives exported to MAT: %d', ...
                totalNormalRepresentatives); ...
                sprintf( ...
                'Excluded from analytical MAT: %d', ...
                totalExcludedFromMAT); ...
                sprintf( ...
                '  Undersized: %d', ...
                totalUndersized); ...
                sprintf( ...
                '  Singleton: %d', ...
                totalSingleton); ...
                sprintf( ...
                '  Oversized: %d', ...
                totalOversized); ...
                sprintf( ...
                'Spectral-QC flagged: %d', ...
                totalSpectralFlagged); ...
                ''; ...
                'SCAN RESULTS'};
            
            % =============================================================
            % 12. PER-SCAN REPORT
            % =============================================================
            
            for sampleNumber = 1:numberSamples
                
                R = ...
                    results(sampleNumber);
                
                normalCount = ...
                    nnz( ...
                    string(R.SpatialStatus(:)) == ...
                    "Normal");
                
                excludedCount = ...
                    R.NumberRepresentatives - ...
                    normalCount;
                
                reportLines = ...
                    [ ...
                    reportLines; ...
                    { ...
                    ''; ...
                    sprintf( ...
                    '%s', ...
                    R.SampleName); ...
                    sprintf( ...
                    '  Input pixels: %d', ...
                    R.InputPixelCount); ...
                    sprintf( ...
                    '  Represented pixels: %d', ...
                    R.RepresentedPixelCount); ...
                    sprintf( ...
                    '  Original connected islands: %d', ...
                    R.SourceIslandCount); ...
                    sprintf( ...
                    '  Total representatives generated: %d', ...
                    R.NumberRepresentatives); ...
                    sprintf( ...
                    '  Normal exported to MAT: %d', ...
                    normalCount); ...
                    sprintf( ...
                    '  Excluded from MAT: %d', ...
                    excludedCount); ...
                    sprintf( ...
                    '    Undersized=%d | Singleton=%d | Oversized=%d', ...
                    R.UndersizedCount, ...
                    R.SingletonCount, ...
                    R.OversizedCount); ...
                    sprintf( ...
                    '  Pixels/rep median: %.1f | range: %d-%d', ...
                    R.MedianPixelsPerRepresentative, ...
                    R.MinimumPixelsPerRepresentative, ...
                    R.MaximumPixelsPerRepresentative); ...
                    sprintf( ...
                    '  Spectral QC flagged: %d', ...
                    R.SpectralQC.FlaggedCount)}]; %#ok<AGROW>
            end
            
            % =============================================================
            % 13. OUTPUT PATHS
            % =============================================================
            
            reportLines = ...
                [ ...
                reportLines; ...
                { ...
                ''; ...
                'QC SUMMARY FIGURE'; ...
                qcFigurePath; ...
                ''; ...
                'MAP FIGURES'; ...
                sprintf( ...
                'Saved map figures: %d', ...
                numel(mapFigurePaths))}];
            
            for mapNumber = 1:numel(mapFigurePaths)
                
                reportLines = ...
                    [ ...
                    reportLines; ...
                    {char(mapFigurePaths(mapNumber))}]; %#ok<AGROW>
            end
            
            reportLines = ...
                [ ...
                reportLines; ...
                { ...
                ''; ...
                'OUTPUT ROOT'; ...
                app.SingleExtractOutputRoot; ...
                ''; ...
                'ANALYTICAL DATASET RULE'; ...
                ['Only Normal spatial representatives are stored in ' ...
                'MeanSpectra of the analytical MAT files.']; ...
                ['Undersized, Singleton and Oversized spatial representatives ' ...
                'remain traceable in provenance/membership files.']; ...
                ''; ...
                ['Experimental grouping is intentionally deferred to ' ...
                'the downstream single-analysis workflow.']}];
            
            app.TextArea_2.Value = ...
                reportLines;
            
            % =============================================================
            % 14. FINAL USER MESSAGE
            % =============================================================
            
            uialert( ...
                app.UIFigure, ...
                sprintf([ ...
                'Extraction completed.\n\n' ...
                'Scans: %d\n' ...
                'Input pixels: %d\n' ...
                'Total representatives: %d\n' ...
                'Normal exported to MAT: %d\n' ...
                'QC-excluded from MAT: %d\n\n' ...
                'QC summary and map figures were saved.'], ...
                numberSamples, ...
                totalInputPixels, ...
                totalGeneratedRepresentatives, ...
                totalNormalRepresentatives, ...
                totalExcludedFromMAT), ...
                'Representative datasets ready');
            
        end
        
        
        function [targetPixels, modeName, accepted] = ...
                requestSingleExtractResolution(app)
            
            targetPixels = ...
                NaN;
            
            modeName = ...
                '';
            
            accepted = ...
                false;
            
            choice = ...
                uiconfirm( ...
                app.UIFigure, ...
                { ...
                'Choose representative spatial resolution:'; ...
                ''; ...
                'Automatic:'; ...
                sprintf( ...
                ['uses the smallest loaded scan and requires approximately ' ...
                'at least %d representatives for that scan.'], ...
                app.SingleExtractMinimumRepresentatives); ...
                ''; ...
                'Expected pixels/mean:'; ...
                ['you define the approximate number of pixels contributing ' ...
                'to each representative mean.']}, ...
                'Representative resolution', ...
                'Options', ...
                { ...
                'Automatic', ...
                'Expected pixels/mean', ...
                'Cancel'}, ...
                'DefaultOption', ...
                'Automatic', ...
                'CancelOption', ...
                'Cancel');
            
            switch choice
                
                case 'Automatic'
                    
                    targetPixels = ...
                        app.determineAutomaticPixelsPerMean( ...
                        1:numel(app.SingleExtractSamples));
                    
                    modeName = ...
                        'Automatic';
                    
                    accepted = ...
                        true;
                    
                case 'Expected pixels/mean'
                    
                    if isfinite( ...
                            app.SingleExtractCurrentTargetPixelsPerMean)
                        
                        expectedDefault = ...
                            num2str( ...
                            app.SingleExtractCurrentTargetPixelsPerMean);
                        
                    else
                        
                        expectedDefault = ...
                            num2str( ...
                            app.determineAutomaticPixelsPerMean( ...
                            1:numel(app.SingleExtractSamples)));
                    end
                    
                    answer = ...
                        inputdlg( ...
                        { ...
                        ['Expected pixels contributing to one ' ...
                        'representative mean:']}, ...
                        'Expected representative resolution', ...
                        [1 60], ...
                        {expectedDefault});
                    
                    if isempty(answer)
                        return
                    end
                    
                    requestedPixels = ...
                        round( ...
                        str2double(answer{1}));
                    
                    if ~isfinite(requestedPixels) || ...
                            requestedPixels < ...
                            app.SingleExtractMinimumPixelsPerMean
                        
                        uialert( ...
                            app.UIFigure, ...
                            sprintf( ...
                            ['Expected pixels/mean must be an integer >= %d.'], ...
                            app.SingleExtractMinimumPixelsPerMean), ...
                            'Invalid representative resolution');
                        
                        return
                    end
                    
                    targetPixels = ...
                        requestedPixels;
                    
                    modeName = ...
                        'User expected';
                    
                    accepted = ...
                        true;
                    
                otherwise
                    
                    return
            end
            
            app.SingleExtractCurrentTargetPixelsPerMean = ...
                targetPixels;
            
        end
        
        
        function outputInfo = saveSingleExtractScanDataset( ...
                app, ...
                scanResult, ...
                resolutionMode)
            
            % =============================================================
            % SAVE ONE SCAN-WISE SINGLE-SPECTRA DATASET
            %
            % OUTPUT:
            %
            %   Data input\single\<ScanName>\
            %
            %       <ScanName>.mat
            %       <ScanName>_provenance.txt
            %       <ScanName>_membership.txt
            %       spatial_source.mat
            %
            % IMPORTANT STORAGE RULE:
            %
            % spatial_source.mat contains ONLY lightweight provenance.
            % It NEVER stores:
            %   - Raman cube
            %   - spectra matrix
            %   - band map
            %   - mask
            %
            % Spatial distribution later reconstructs maps by following
            % the source paths stored in spatial_source.mat.
            % =============================================================
            
            
            % =============================================================
            % 1. SAFE SCAN NAME
            % =============================================================
            
            safeScanName = ...
                app.makeSafeFileName( ...
                scanResult.SampleName);
            
            
            if isempty(safeScanName)
                
                error( ...
                    'single_extract:InvalidScanOutputName', ...
                    'Scan name cannot be converted to a valid folder name.');
                
            end
            
            
            % =============================================================
            % 2. OUTPUT FOLDER
            % =============================================================
            
            if isempty(app.SingleExtractOutputRoot)
                
                error( ...
                    'single_extract:SingleOutputRootMissing', ...
                    ['Single spectra output root is empty.' newline ...
                    'SingleInputRoot must be supplied by the upstream workflow.']);
                
            end
            
            
            outputFolder = ...
                fullfile( ...
                app.SingleExtractOutputRoot, ...
                safeScanName);
            
            
            if ~exist(outputFolder, 'dir')
                
                [created, mkdirMessage] = ...
                    mkdir(outputFolder);
                
                
                if ~created
                    
                    error( ...
                        'single_extract:CannotCreateSingleOutputFolder', ...
                        sprintf( ...
                        ['Could not create output folder:\n\n%s\n\n%s'], ...
                        outputFolder, ...
                        mkdirMessage));
                    
                end
                
            end
            
            
            % =============================================================
            % 3. OUTPUT PATHS
            % =============================================================
            
            matPath = ...
                fullfile( ...
                outputFolder, ...
                [safeScanName '.mat']);
            
            
            provenancePath = ...
                fullfile( ...
                outputFolder, ...
                [safeScanName '_provenance.txt']);
            
            
            membershipPath = ...
                fullfile( ...
                outputFolder, ...
                [safeScanName '_membership.txt']);
            
            
            spatialSourcePath = ...
                fullfile( ...
                outputFolder, ...
                'spatial_source.mat');


            % =============================================================
            % 4. ANALYTICAL FILTER
            %
            % Only SpatialStatus == "Normal" enters the analytical MAT.
            %
            % Undersized / Singleton / Oversized representatives remain
            % documented in provenance + membership.
            % =============================================================
            
            normalMask = ...
                string(scanResult.SpatialStatus(:)) == ...
                "Normal";
            
            
            Wavenumber = ...
                double( ...
                scanResult.Wavenumber(:));
            
            
            MeanSpectra = ...
                double( ...
                scanResult.MeanSpectra( ...
                normalMask, :));
            
            
            if isempty(MeanSpectra)
                
                warning( ...
                    'single_extract:NoNormalRepresentative', ...
                    ['Scan "%s" has no Normal representatives. ' ...
                    'MAT file will contain an empty MeanSpectra matrix.'], ...
                    scanResult.SampleName);
                
            end
            
            
            % =============================================================
            % 5. SAVE MINIMAL ANALYTICAL MAT
            %
            % This file remains intentionally minimal because single_mode
            % needs only Wavenumber + representative spectra.
            % =============================================================
            
            save( ...
                matPath, ...
                'Wavenumber', ...
                'MeanSpectra', ...
                '-v7.3');
            
            
            % =============================================================
            % 6. FULL EXTRACTION PROVENANCE
            % =============================================================
            
            app.writeSingleExtractScanProvenance( ...
                provenancePath, ...
                scanResult, ...
                resolutionMode);
            
            
            app.writeSingleExtractMembership( ...
                membershipPath, ...
                scanResult);
            
            
            % =============================================================
            % 7. RESOLVE ORIGINAL LOADED SAMPLE RECORD
            %
            % This is critical.
            %
            % SingleExtractSamples contains the real links:
            %
            %   ReadableMATPath
            %   ClusterMATPath
            %   RunFolder
            %   ExtractionFolder
            %
            % Do NOT use CurrentScanName / SourceMatPath here because
            % Generate can process several saved scans in one batch.
            % =============================================================
            
            sampleIndex = ...
                [];
            
            
            if ~isempty(app.SingleExtractSamples)
                
                sampleNames = ...
                    string( ...
                    {app.SingleExtractSamples.Name});
                
                
                sampleIndex = ...
                    find( ...
                    sampleNames == ...
                    string(scanResult.SampleName), ...
                    1, ...
                    'first');
                
            end
            
            
            if isempty(sampleIndex)
                
                error( ...
                    'single_extract:SpatialSourceSampleNotFound', ...
                    sprintf( ...
                    ['Could not match generated scan "%s" back to ' ...
                    'SingleExtractSamples.'], ...
                    scanResult.SampleName));
                
            end
            
            
            sourceSample = ...
                app.SingleExtractSamples(sampleIndex);
            
            % =============================================================
            % RESOLVE READABLE MAT PATH
            % =============================================================
            
            readableMATPath = ...
                char(string(sourceSample.ReadableMATPath));
            
            if isempty(readableMATPath)
                
                error( ...
                    'single_extract:ReadableMATPathMissing', ...
                    sprintf( ...
                    ['ReadableMATPath is empty for scan "%s".'], ...
                    scanResult.SampleName));
            end
            
            if ~isfile(readableMATPath)
                
                error( ...
                    'single_extract:ReadableMATFileMissing', ...
                    sprintf( ...
                    ['Readable Raman MAT file does not exist:\n\n%s'], ...
                    readableMATPath));
            end
            
            
            % =============================================================
            % 8. VALIDATE SPATIAL SOURCE CUBE
            %
            % ReadableMATPath has already been loaded by
            % loadReadableRamanCube during "Load clusters".
            %
            % Recheck the path itself before writing provenance.
            % =============================================================
            
            readableMATPath = ...
                string(sourceSample.ReadableMATPath);
            
            
            if strlength(readableMATPath) == 0 || ...
                    ~isfile(readableMATPath)
                
                error( ...
                    'single_extract:SpatialReadableSourceMissing', ...
                    sprintf( ...
                    ['The source Raman MAT required for future spatial ' ...
                    'reconstruction does not exist:\n\n%s'], ...
                    readableMATPath));
                
            end
            
            
            % =============================================================
            % 9. GET SOURCE DIMENSIONS WITHOUT COPYING CUBE
            %
            % loadReadableRamanCube is NOT called again here.
            %
            % We already have:
            %   BandMap spatial size
            %   ReadableWavenumber spectral size
            %
            % Therefore no second full Raman cube is loaded.
            % =============================================================
            
            sourceNy = ...
                size(sourceSample.BandMap, 1);
            
            
            sourceNx = ...
                size(sourceSample.BandMap, 2);
            
            
            sourceNWn = ...
                numel(sourceSample.ReadableWavenumber);
            
            
            readableWN = ...
                double( ...
                sourceSample.ReadableWavenumber(:));
            
            
            if isempty(readableWN)
                
                sourceWnMin = ...
                    NaN;
                
                sourceWnMax = ...
                    NaN;
                
            else
                
                sourceWnMin = ...
                    min(readableWN, [], 'omitnan');
                
                sourceWnMax = ...
                    max(readableWN, [], 'omitnan');
                
            end
            
            
            % =============================================================
            % 10. BUILD LIGHTWEIGHT SPATIAL SOURCE SIDECAR
            %
            % This is the canonical bridge:
            %
            % single_extract
            %       ->
            % Data input\single\<scan>\spatial_source.mat
            %       ->
            % single_mode Spatial distribution
            %
            % NO HEAVY DATA.
            % =============================================================
            
            SpatialSource = ...
                struct();
            
            
            SpatialSource.SchemaVersion = ...
                'ramancube_spatial_source_v1';
            
            
            SpatialSource.CreatedAt = ...
                char( ...
                datetime( ...
                'now', ...
                'Format', ...
                'yyyy-MM-dd HH:mm:ss.SSS'));
            
            
            SpatialSource.SourceApplication = ...
                'single_extract';
            
            
            % -------------------------------------------------------------
            % DATASET IDENTITY
            % -------------------------------------------------------------
            
            SpatialSource.ScanName = ...
                char( ...
                string(scanResult.SampleName));
            
            
            % Use sample name as stable fallback ScanID in saved-package mode.
            SpatialSource.ScanID = ...
                safeScanName;
            
            
            % -------------------------------------------------------------
            % PRIMARY SPATIAL SOURCE
            %
            % This is the file that actually contains the spatial Raman cube.
            % -------------------------------------------------------------
            
            SpatialSource.ProcessedCubePath = ...
                char(readableMATPath);
            
            
            SpatialSource.ReadableMATPath = ...
                char(readableMATPath);
            
            
            % -------------------------------------------------------------
            % UPSTREAM EXTRACTION / CLUSTER PROVENANCE
            % -------------------------------------------------------------
            
            SpatialSource.ClusterMATPath = ...
                char( ...
                string(sourceSample.ClusterMATPath));
            
            
            SpatialSource.ClusterRunFolder = ...
                char( ...
                string(sourceSample.RunFolder));
            
            
            SpatialSource.ExtractionFolder = ...
                char( ...
                string(sourceSample.ExtractionFolder));
            
            
            % -------------------------------------------------------------
            % CURRENT SINGLE-ANALYSIS INPUT
            % -------------------------------------------------------------
            
            SpatialSource.SingleDatasetFolder = ...
                outputFolder;
            
            
            SpatialSource.AnalyticalMATPath = ...
                matPath;
            
            
            SpatialSource.ProvenancePath = ...
                provenancePath;
            
            
            SpatialSource.MembershipPath = ...
                membershipPath;
            
            
            % -------------------------------------------------------------
            % SOURCE STAGE
            % -------------------------------------------------------------
            
            SpatialSource.SourceStage = ...
                'Pretreated / readable Raman cube';
            
            
            % -------------------------------------------------------------
            % DIMENSION METADATA
            %
            % Small scalars only.
            % -------------------------------------------------------------
            
            SpatialSource.Ny = ...
                sourceNy;
            
            
            SpatialSource.Nx = ...
                sourceNx;
            
            
            SpatialSource.NWn = ...
                sourceNWn;
            
            
            SpatialSource.WnMin = ...
                sourceWnMin;
            
            
            SpatialSource.WnMax = ...
                sourceWnMax;
            
            
            % -------------------------------------------------------------
            % EXTRACTION SUMMARY
            % -------------------------------------------------------------
            
            SpatialSource.TotalRepresentatives = ...
                scanResult.NumberRepresentatives;
            
            
            SpatialSource.NormalRepresentatives = ...
                nnz(normalMask);
            
            
            SpatialSource.ExcludedRepresentatives = ...
                scanResult.NumberRepresentatives - ...
                nnz(normalMask);
            
            
            SpatialSource.TargetPixelsPerMean = ...
                scanResult.TargetPixelsPerMean;
            
            
            SpatialSource.ResolutionMode = ...
                char(string(resolutionMode));
            
            
            % -------------------------------------------------------------
            % STORAGE POLICY DECLARATION
            % -------------------------------------------------------------
            
            SpatialSource.ContainsCube = ...
                false;
            
            
            SpatialSource.ContainsSpectra = ...
                false;
            
            
            SpatialSource.ContainsMap = ...
                false;
            
            
            SpatialSource.StoragePolicy = ...
                ['Lightweight provenance only. Raman spatial data are ' ...
                'referenced by file path and are not duplicated.'];
            
            
            % =============================================================
            % 11. SAVE SPATIAL SIDECAR
            %
            % Deliberately NOT -v7.3.
            %
            % This is a tiny metadata structure and should remain a small
            % ordinary MAT file.
            % =============================================================
            
            save( ...
                spatialSourcePath, ...
                'SpatialSource');
            
            
            % =============================================================
            % 12. OUTPUT RECORD
            % =============================================================
            
            outputInfo = ...
                struct();
            
            
            outputInfo.SampleName = ...
                scanResult.SampleName;
            
            
            outputInfo.OutputFolder = ...
                outputFolder;
            
            
            outputInfo.MATPath = ...
                matPath;
            
            
            outputInfo.ProvenancePath = ...
                provenancePath;
            
            
            outputInfo.MembershipPath = ...
                membershipPath;
            
            
            outputInfo.SpatialSourcePath = ...
                spatialSourcePath;
            
            
            outputInfo.SourceReadableMATPath = ...
                char(readableMATPath);
            
            
            outputInfo.SourceClusterMATPath = ...
                sourceSample.ClusterMATPath;
            
            
            outputInfo.TotalRepresentatives = ...
                scanResult.NumberRepresentatives;
            
            
            outputInfo.NormalRepresentatives = ...
                nnz(normalMask);
            
            
            outputInfo.ExcludedRepresentatives = ...
                scanResult.NumberRepresentatives - ...
                nnz(normalMask);
            
            
        end
        
        
        function writeSingleExtractScanProvenance( ...
                app, ...
                txtPath, ...
                scanResult, ...
                resolutionMode)
            
            fileID = ...
                fopen( ...
                txtPath, ...
                'w');
            
            if fileID < 0
                
                error( ...
                    'single_extract:ProvenanceOpenFailed', ...
                    sprintf( ...
                    'Could not create provenance file:\n%s', ...
                    txtPath));
            end
            
            cleanupObject = ...
                onCleanup( ...
                @() fclose(fileID)); %#ok<NASGU>
            
            sourceSample = ...
                app.SingleExtractSamples( ...
                scanResult.SampleIndex);
            
            fprintf( ...
                fileID, ...
                'RAMANCUBE SCAN-WISE REPRESENTATIVE EXTRACTION\n');
            
            fprintf( ...
                fileID, ...
                '============================================================\n\n');
            
            fprintf( ...
                fileID, ...
                'Created: %s\n', ...
                char(datetime( ...
                'now', ...
                'Format', ...
                'yyyy-MM-dd HH:mm:ss.SSS')));
            
            fprintf( ...
                fileID, ...
                'Extraction version: %s\n', ...
                app.SingleExtractRepresentativeVersion);
            
            fprintf( ...
                fileID, ...
                'Scan: %s\n', ...
                scanResult.SampleName);
            
            fprintf( ...
                fileID, ...
                'Source cluster MAT: %s\n', ...
                sourceSample.ClusterMATPath);
            
            fprintf( ...
                fileID, ...
                'Source extraction run: %s\n', ...
                sourceSample.RunFolder);
            
            fprintf( ...
                fileID, ...
                'Resolution mode: %s\n', ...
                resolutionMode);
            
            fprintf( ...
                fileID, ...
                'Target pixels per representative: %d\n', ...
                scanResult.TargetPixelsPerMean);
            
            fprintf( ...
                fileID, ...
                'Automatic minimum-representative setting: %d\n', ...
                app.SingleExtractMinimumRepresentatives);
            
            fprintf( ...
                fileID, ...
                'Spatial connectivity: %d-neighbour\n', ...
                app.SingleExtractSpatialConnectivity);
            
            fprintf( ...
                fileID, ...
                'Support tolerance: +/- %.1f%%\n', ...
                100 * app.SingleExtractSupportToleranceFraction);
            
            fprintf( ...
                fileID, ...
                '\nPIXEL CONSERVATION\n');
            
            fprintf( ...
                fileID, ...
                '------------------------------------------------------------\n');
            
            fprintf( ...
                fileID, ...
                'Input cluster pixels: %d\n', ...
                scanResult.InputPixelCount);
            
            fprintf( ...
                fileID, ...
                'Represented pixels: %d\n', ...
                scanResult.RepresentedPixelCount);
            
            fprintf( ...
                fileID, ...
                'All pixels preserved exactly once: %d\n', ...
                scanResult.AllPixelsPreserved);
            
            fprintf( ...
                fileID, ...
                '\nSPATIAL SUBDIVISION\n');
            
            fprintf( ...
                fileID, ...
                '------------------------------------------------------------\n');
            
            fprintf( ...
                fileID, ...
                'Original connected islands: %d\n', ...
                scanResult.SourceIslandCount);
            
            normalMask = ...
                string(scanResult.SpatialStatus(:)) == "Normal";
            
            normalCountForMAT = ...
                nnz(normalMask);
            
            excludedCountForMAT = ...
                scanResult.NumberRepresentatives - ...
                normalCountForMAT;
            
            fprintf( ...
                fileID, ...
                'Final representatives generated (all statuses): %d\n', ...
                scanResult.NumberRepresentatives);
            
            fprintf( ...
                fileID, ...
                'Normal representatives exported to analytical MAT: %d\n', ...
                normalCountForMAT);
            
            fprintf( ...
                fileID, ...
                'Representatives excluded from analytical MAT: %d\n', ...
                excludedCountForMAT);
            
            fprintf( ...
                fileID, ...
                'All representative patches connected: %d\n', ...
                scanResult.AllRepresentativesConnected);
            
            fprintf( ...
                fileID, ...
                'Median pixels / representative: %.3f\n', ...
                scanResult.MedianPixelsPerRepresentative);
            
            fprintf( ...
                fileID, ...
                'Minimum pixels / representative: %d\n', ...
                scanResult.MinimumPixelsPerRepresentative);
            
            fprintf( ...
                fileID, ...
                'Maximum pixels / representative: %d\n', ...
                scanResult.MaximumPixelsPerRepresentative);
            
            fprintf( ...
                fileID, ...
                'Aggregation factor: %.4f source pixels / representative\n', ...
                scanResult.AggregationFactor);
            
            fprintf( ...
                fileID, ...
                '\nSPATIAL QC STATUS\n');
            
            fprintf( ...
                fileID, ...
                '------------------------------------------------------------\n');
            
            fprintf( ...
                fileID, ...
                'Normal: %d\n', ...
                scanResult.NormalCount);
            
            fprintf( ...
                fileID, ...
                'Undersized: %d\n', ...
                scanResult.UndersizedCount);
            
            fprintf( ...
                fileID, ...
                'Singleton: %d\n', ...
                scanResult.SingletonCount);
            
            fprintf( ...
                fileID, ...
                'Oversized: %d\n', ...
                scanResult.OversizedCount);
            
            fprintf( ...
                fileID, ...
                '\nSPECTRAL QC\n');
            
            fprintf( ...
                fileID, ...
                '------------------------------------------------------------\n');
            
            fprintf( ...
                fileID, ...
                'Spectral QC enabled: %d\n', ...
                app.SingleExtractSpectralQCEnabled);
            
            fprintf( ...
                fileID, ...
                'Flagged representatives: %d / %d\n', ...
                scanResult.SpectralQC.FlaggedCount, ...
                scanResult.NumberRepresentatives);
            
            fprintf( ...
                fileID, ...
                'Robust median dispersion: %.8g\n', ...
                scanResult.SpectralQC.RobustMedian);
            
            fprintf( ...
                fileID, ...
                'Robust MAD: %.8g\n', ...
                scanResult.SpectralQC.RobustMAD);
            
            fprintf( ...
                fileID, ...
                'Flag threshold: %.8g\n', ...
                scanResult.SpectralQC.FlagThreshold);
            
            fprintf( ...
                fileID, ...
                '\nREPRESENTATIVE SUMMARY\n');
            
            fprintf( ...
                fileID, ...
                '------------------------------------------------------------\n');
            
            for representativeNumber = ...
                    1:scanResult.NumberRepresentatives
                
                fprintf( ...
                    fileID, ...
                    [ ...
                    'Rep %04d | pixels=%d | island=%d | ' ...
                    'centroid=(%.3f,%.3f) | spatial=%s | ' ...
                    'components=%d | dispersion=%.8g | spectralQC=%s\n'], ...
                    representativeNumber, ...
                    scanResult.PixelsPerRepresentative(representativeNumber), ...
                    scanResult.IslandID(representativeNumber), ...
                    scanResult.CentroidRow(representativeNumber), ...
                    scanResult.CentroidColumn(representativeNumber), ...
                    char(scanResult.SpatialStatus(representativeNumber)), ...
                    scanResult.ConnectedComponentCount(representativeNumber), ...
                    scanResult.SpectralQC.Dispersion(representativeNumber), ...
                    app.singleExtractPassFlagText( ...
                    scanResult.SpectralQC.Flagged(representativeNumber)));
            end
            
            fprintf( ...
                fileID, ...
                '\nIMPORTANT INTERPRETATION\n');
            
            fprintf( ...
                fileID, ...
                '------------------------------------------------------------\n');
            
            fprintf( ...
                fileID, ...
                ['Representative rows are spatially aggregated observations ' ...
                'within one source scan.\n']);
            
            fprintf( ...
                fileID, ...
                ['They must not be interpreted as independent biological ' ...
                'replicates.\n']);
            
            fprintf( ...
                fileID, ...
                ['Experimental grouping and scan-level validation should be ' ...
                'performed downstream.\n']);
            fprintf( ...
                fileID, ...
                ['Only representatives with spatial status "Normal" were exported ' ...
                'to the analytical MAT file.\n']);
            
            fprintf( ...
                fileID, ...
                ['Undersized, Singleton and Oversized representatives remain ' ...
                'documented in provenance and membership files.\n']);
            
        end
        
        
        function writeSingleExtractMembership( ...
                app, ...
                txtPath, ...
                scanResult)
            
            fileID = ...
                fopen( ...
                txtPath, ...
                'w');
            
            if fileID < 0
                
                error( ...
                    'single_extract:MembershipOpenFailed', ...
                    sprintf( ...
                    'Could not create membership file:\n%s', ...
                    txtPath));
            end
            
            cleanupObject = ...
                onCleanup( ...
                @() fclose(fileID)); %#ok<NASGU>
            
            sourceSample = ...
                app.SingleExtractSamples( ...
                scanResult.SampleIndex);
            
            mapSize = ...
                size( ...
                sourceSample.ClusterPackage.Map.ClusterMask2D);
            
            fprintf( ...
                fileID, ...
                ['RepresentativeID\tPixelIdx\tRow\tColumn\tIslandID\t' ...
                'SpatialStatus\tSpectralQCFlag\n']);
            
            for representativeNumber = ...
                    1:scanResult.NumberRepresentatives
                
                pixelIdx = ...
                    double( ...
                    scanResult.SpatialGroups{representativeNumber}(:));
                
                [row, column] = ...
                    ind2sub( ...
                    mapSize, ...
                    pixelIdx);
                
                statusText = ...
                    char( ...
                    scanResult.SpatialStatus(representativeNumber));
                
                if scanResult.SpectralQC.Flagged(representativeNumber)
                    
                    spectralFlag = ...
                        'FLAGGED';
                    
                else
                    
                    spectralFlag = ...
                        'PASS';
                end
                
                for pixelNumber = 1:numel(pixelIdx)
                    
                    fprintf( ...
                        fileID, ...
                        '%d\t%d\t%d\t%d\t%d\t%s\t%s\n', ...
                        representativeNumber, ...
                        pixelIdx(pixelNumber), ...
                        row(pixelNumber), ...
                        column(pixelNumber), ...
                        scanResult.IslandID(representativeNumber), ...
                        statusText, ...
                        spectralFlag);
                end
            end
            
        end
        
        
        function result = generateRepresentativesForOneSample( ...
                app, ...
                sampleIndex, ...
                targetPixels)
            
            S = ...
                app.SingleExtractSamples(sampleIndex);
            
            P = ...
                S.ClusterPackage;
            
            clusterMask = ...
                logical( ...
                P.Map.ClusterMask2D);
            
            spectra = ...
                double( ...
                P.Spectra.Pretreated);
            
            wn = ...
                double( ...
                P.Wavenumber(:));
            
            packagePixelIdx = ...
                double( ...
                P.Pixels.PixelIdx(:));
            
            % =============================================================
            % 1. PACKAGE ALIGNMENT
            % =============================================================
            
            if size(spectra, 1) ~= ...
                    numel(packagePixelIdx)
                
                error( ...
                    'single_extract:PackagePixelAlignment', ...
                    sprintf([ ...
                    'PixelIdx and Pretreated spectra row counts differ ' ...
                    'for sample "%s".'], ...
                    S.Name));
            end
            
            if size(spectra, 2) ~= ...
                    numel(wn)
                
                error( ...
                    'single_extract:PackageSpectralAlignment', ...
                    sprintf([ ...
                    'Wavenumber and Pretreated spectral dimensions differ ' ...
                    'for sample "%s".'], ...
                    S.Name));
            end
            
            maskPixelIdx = ...
                double(find(clusterMask));
            
            if ~isequal( ...
                    sort(maskPixelIdx(:)), ...
                    sort(packagePixelIdx(:)))
                
                error( ...
                    'single_extract:MaskPackagePixelMismatch', ...
                    sprintf([ ...
                    'Cluster mask pixels and ClusterPackage.Pixels.PixelIdx ' ...
                    'are not identical for sample "%s".'], ...
                    S.Name));
            end
            
            % =============================================================
            % 2. CONNECTED SPATIAL PARTITION
            % =============================================================
            
            partition = ...
                app.buildBalancedSpatialPartition( ...
                clusterMask, ...
                targetPixels);
            
            numberRepresentatives = ...
                partition.NumberRepresentatives;
            
            representativeSpectra = ...
                nan( ...
                numberRepresentatives, ...
                numel(wn));
            
            sourceSpectrumRows = ...
                cell(numberRepresentatives, 1);
            
            % =============================================================
            % 3. MEAN PRETREATED SPECTRUM OF EACH SPATIAL PATCH
            % =============================================================
            
            for representativeNumber = ...
                    1:numberRepresentatives
                
                spatialPixelIdx = ...
                    double( ...
                    partition.Groups{representativeNumber}(:));
                
                [present, spectrumRows] = ...
                    ismember( ...
                    spatialPixelIdx, ...
                    packagePixelIdx);
                
                if ~all(present)
                    
                    error( ...
                        'single_extract:RepresentativePixelAlignment', ...
                        sprintf([ ...
                        'Could not align every spatial pixel for sample "%s".'], ...
                        S.Name));
                end
                
                localSpectra = ...
                    spectra(spectrumRows, :);
                
                representativeSpectra( ...
                    representativeNumber, :) = ...
                    mean( ...
                    localSpectra, ...
                    1, ...
                    'omitnan');
                
                sourceSpectrumRows{representativeNumber} = ...
                    spectrumRows(:);
            end
            
            % =============================================================
            % 4. SPECTRAL HETEROGENEITY QC
            %
            % Descriptive only.
            % It never removes or changes representative spectra.
            % =============================================================
            
            if app.SingleExtractSpectralQCEnabled
                
                qc = ...
                    app.assessRepresentativeSpectralHeterogeneity( ...
                    spectra, ...
                    partition.Groups, ...
                    packagePixelIdx);
                
            else
                
                qc = struct();
                
                qc.Dispersion = ...
                    nan(numberRepresentatives, 1);
                
                qc.RobustMedian = ...
                    NaN;
                
                qc.RobustMAD = ...
                    NaN;
                
                qc.FlagThreshold = ...
                    NaN;
                
                qc.Flagged = ...
                    false(numberRepresentatives, 1);
                
                qc.FlaggedCount = ...
                    0;
            end
            
            % =============================================================
            % 5. SUMMARY COUNTS
            % =============================================================
            
            spatialStatus = ...
                string( ...
                partition.SpatialStatus(:));
            
            normalCount = ...
                nnz(spatialStatus == "Normal");
            
            undersizedCount = ...
                nnz(spatialStatus == "Undersized");
            
            singletonCount = ...
                nnz(spatialStatus == "Singleton");
            
            oversizedCount = ...
                nnz(spatialStatus == "Oversized");
            
            aggregationFactor = ...
                partition.InputPixelCount / ...
                max(1, numberRepresentatives);
            
            % =============================================================
            % 6. OUTPUT
            % =============================================================
            
            result = struct();
            
            result.SampleName = ...
                S.Name;
            
            result.SampleIndex = ...
                sampleIndex;
            
            result.Wavenumber = ...
                wn;
            
            result.MeanSpectra = ...
                representativeSpectra;
            
            result.NumberRepresentatives = ...
                numberRepresentatives;
            
            result.TargetPixelsPerMean = ...
                targetPixels;
            
            result.InputPixelCount = ...
                partition.InputPixelCount;
            
            result.RepresentedPixelCount = ...
                partition.RepresentedPixelCount;
            
            result.SourceIslandCount = ...
                partition.SourceIslandCount;
            
            result.PixelsPerRepresentative = ...
                partition.GroupSizes;
            
            result.MedianPixelsPerRepresentative = ...
                median(partition.GroupSizes);
            
            result.MinimumPixelsPerRepresentative = ...
                min(partition.GroupSizes);
            
            result.MaximumPixelsPerRepresentative = ...
                max(partition.GroupSizes);
            
            result.AggregationFactor = ...
                aggregationFactor;
            
            result.SpatialGroups = ...
                partition.Groups;
            
            result.IslandID = ...
                partition.IslandID;
            
            result.CentroidRow = ...
                partition.CentroidRow;
            
            result.CentroidColumn = ...
                partition.CentroidColumn;
            
            result.ConnectedComponentCount = ...
                partition.ConnectedComponentCount;
            
            result.SpatialStatus = ...
                spatialStatus;
            
            result.SourceSpectrumRows = ...
                sourceSpectrumRows;
            
            result.SpectralQC = ...
                qc;
            
            result.NormalCount = ...
                normalCount;
            
            result.UndersizedCount = ...
                undersizedCount;
            
            result.SingletonCount = ...
                singletonCount;
            
            result.OversizedCount = ...
                oversizedCount;
            
            result.AllPixelsPreserved = ...
                partition.AllPixelsPreserved;
            
            result.AllRepresentativesConnected = ...
                partition.AllRepresentativesConnected;
            
        end
        
        
        function displaySelectedSpectralGroupText(app, groups)
            
            if isempty(groups)
                return
            end
            
            if numel(groups) == 1
                
                group = ...
                    groups(1);
                
                textLines = { ...
                    'SELECTED SPECTRAL GROUP'; ...
                    '================================================'; ...
                    ''; ...
                    ['Name: ' group.Name]; ...
                    ['Node type: ' group.Type]; ...
                    sprintf( ...
                    'Source cluster ID: %g', ...
                    group.ClusterID); ...
                    sprintf( ...
                    'Pixels: %d', ...
                    numel(group.PixelIdx))};
                
                if ~isempty(group.PurityClass)
                    
                    textLines = ...
                        [ ...
                        textLines; ...
                        { ...
                        ['Purity component: ' ...
                        group.PurityClass]}];
                end
                
                if strcmp(group.Type, 'purity_component')
                    
                    textLines = ...
                        [ ...
                        textLines; ...
                        { ...
                        ''; ...
                        'INTERPRETATION'; ...
                        ['This is a pixel-level composition group derived ' ...
                        'inside its source segmentation cluster.']; ...
                        ['It can be compared spectrally with any cluster or ' ...
                        'other purity group.']; ...
                        ['It does not independently modify ClusterLabelMap.']}];
                end
                
            else
                
                combinedPixels = [];
                
                textLines = { ...
                    'MULTIPLE SPECTRAL GROUPS SELECTED'; ...
                    '================================================'; ...
                    ''; ...
                    sprintf( ...
                    'Groups selected: %d', ...
                    numel(groups)); ...
                    ''; ...
                    'SELECTED GROUPS'};
                
                for groupNumber = 1:numel(groups)
                    
                    combinedPixels = ...
                        [ ...
                        combinedPixels; ...
                        groups(groupNumber).PixelIdx(:)]; %#ok<AGROW>
                    
                    textLines{end + 1, 1} = ...
                        sprintf( ...
                        '%s | n=%d', ...
                        groups(groupNumber).Name, ...
                        numel(groups(groupNumber).PixelIdx)); %#ok<AGROW>
                end
                
                textLines = ...
                    [ ...
                    textLines; ...
                    { ...
                    ''; ...
                    sprintf( ...
                    'Total group-pixel entries: %d', ...
                    numel(combinedPixels)); ...
                    sprintf( ...
                    'Unique physical pixels: %d', ...
                    numel(unique(combinedPixels))); ...
                    ''; ...
                    ['Overlapping groups are allowed because a whole cluster ' ...
                    'and one of its purity components may be selected together.']}];
            end
            
            app.TextArea.Value = ...
                textLines;
            
        end
        
        
        function targetPixels = determineAutomaticPixelsPerMean(app, sampleIndices)
            
            if nargin < 2 || isempty(sampleIndices)
                
                sampleIndices = ...
                    1:numel(app.SingleExtractSamples);
            end
            
            pixelCounts = ...
                zeros(numel(sampleIndices), 1);
            
            for i = 1:numel(sampleIndices)
                
                sampleIndex = ...
                    sampleIndices(i);
                
                pixelCounts(i) = ...
                    app.SingleExtractSamples(sampleIndex).PixelCount;
            end
            
            if isempty(pixelCounts) || ...
                    any(pixelCounts < 1)
                
                error( ...
                    'single_extract:InvalidPixelCounts', ...
                    'One or more loaded samples have invalid pixel counts.');
            end
            
            minimumPixels = ...
                min(pixelCounts);
            
            % =============================================================
            % MINIMUM 35 REPRESENTATIVES IN THE SMALLEST SCAN
            % =============================================================
            
            targetPixels = ...
                floor( ...
                minimumPixels / ...
                app.SingleExtractMinimumRepresentatives);
            
            targetPixels = ...
                max( ...
                app.SingleExtractPreferredMinimumPixelsPerMean, ...
                targetPixels);
            
            % Never exceed the smallest scan.
            targetPixels = ...
                min( ...
                targetPixels, ...
                minimumPixels);
            
            targetPixels = ...
                max( ...
                app.SingleExtractMinimumPixelsPerMean, ...
                round(targetPixels));
            
        end
        
        
        function runResults = runLocalSplitKMeansRuns( ...
                app, ...
                localScores, ...
                numberOfSubclusters, ...
                firstRunNumber, ...
                lastRunNumber, ...
                baseSeed)
            
            % =============================================================
            % RUN INDEPENDENT LOCAL K-MEANS SOLUTIONS
            %
            % localScores already belongs to the selected global PCA space.
            % No PCA operation occurs here.
            % =============================================================
            
            runResults = struct( ...
                'RunNumber', {}, ...
                'Seed', {}, ...
                'LabelsAll', {}, ...
                'LabelsTraining', {}, ...
                'Centroids', {}, ...
                'SumDistance', {}, ...
                'TotalSSE', {}, ...
                'MedianARI', {});
            
            for runNumber = firstRunNumber:lastRunNumber
                
                currentSeed = ...
                    baseSeed + runNumber - 1;
                
                rng( ...
                    currentSeed, ...
                    'twister');
                
                [labels, centroids, sumDistance] = ...
                    kmeans( ...
                    localScores, ...
                    numberOfSubclusters, ...
                    'Distance', ...
                    'sqeuclidean', ...
                    'Start', ...
                    'plus', ...
                    'Replicates', ...
                    app.SplitInternalReplicates, ...
                    'MaxIter', ...
                    app.ClusterMaxIter, ...
                    'EmptyAction', ...
                    'singleton', ...
                    'Display', ...
                    'off');
                
                labels = ...
                    double(labels(:));
                
                if numel(unique(labels)) ~= ...
                        numberOfSubclusters
                    
                    error( ...
                        ['Local split run %d produced %d non-empty children ' ...
                        'instead of the requested %d.'], ...
                        runNumber, ...
                        numel(unique(labels)), ...
                        numberOfSubclusters);
                end
                
                currentResult = struct();
                
                currentResult.RunNumber = ...
                    runNumber;
                
                currentResult.Seed = ...
                    currentSeed;
                
                % Every parent pixel participates directly in this local
                % clustering. There is no separate prediction subset.
                currentResult.LabelsAll = ...
                    uint16(labels);
                
                currentResult.LabelsTraining = ...
                    uint16(labels);
                
                currentResult.Centroids = ...
                    double(centroids);
                
                currentResult.SumDistance = ...
                    double(sumDistance(:));
                
                currentResult.TotalSSE = ...
                    sum( ...
                    double(sumDistance), ...
                    'omitnan');
                
                currentResult.MedianARI = ...
                    NaN;
                
                if isempty(runResults)
                    
                    runResults = ...
                        currentResult;
                    
                else
                    
                    runResults(end + 1) = ...
                        currentResult; %#ok<AGROW>
                end
            end
            
        end
        
        function runReferenceComponentAssessment(app)
            
            % =============================================================
            % 1. VALIDATE CLUSTER STATE
            % =============================================================
            
            if ~app.ClusterReady || ...
                    isempty(app.Clusters)
                
                uialert( ...
                    app.UIFigure, ...
                    'Run Cluster segmentation first.', ...
                    'Cluster result required');
                
                return
            end
            
            selectedIDs = ...
                app.SelectedClusterIDs;
            
            if numel(selectedIDs) ~= 1
                
                uialert( ...
                    app.UIFigure, ...
                    { ...
                    'Select exactly one active cluster in the Tree.'; ...
                    ''; ...
                    'Reference-component assessment is performed inside one cluster.'}, ...
                    'Select one cluster');
                
                return
            end
            
            clusterIndex = ...
                app.findClusterIndexByID( ...
                selectedIDs(1));
            
            if isempty(clusterIndex) || ...
                    ~app.Clusters(clusterIndex).Active
                
                uialert( ...
                    app.UIFigure, ...
                    'The selected cluster is not active.', ...
                    'Invalid cluster');
                
                return
            end
            
            C = ...
                app.Clusters(clusterIndex);
            
            % =============================================================
            % 2. COMPONENT NAME AND THRESHOLDS
            % =============================================================
            
            defaults = { ...
                '', ...
                num2str( ...
                app.ComponentAssessmentDefaultLowThreshold, ...
                '%.2f'), ...
                num2str( ...
                app.ComponentAssessmentDefaultHighThreshold, ...
                '%.2f')};
            
            answers = ...
                inputdlg( ...
                { ...
                'Component name, for example Lipid or Protein', ...
                'Intermediate-support lower threshold', ...
                'High-support threshold'}, ...
                ['Reference assessment — ' C.Name], ...
                [1 65], ...
                defaults);
            
            if isempty(answers)
                return
            end
            
            componentName = ...
                strtrim( ...
                char(string(answers{1})));
            
            lowThreshold = ...
                str2double(answers{2});
            
            highThreshold = ...
                str2double(answers{3});
            
            if isempty(componentName)
                
                uialert( ...
                    app.UIFigure, ...
                    'Component name cannot be empty.', ...
                    'Missing component name');
                
                return
            end
            
            if ~isfinite(lowThreshold) || ...
                    ~isfinite(highThreshold) || ...
                    lowThreshold < 0 || ...
                    highThreshold > 1 || ...
                    lowThreshold >= highThreshold
                
                uialert( ...
                    app.UIFigure, ...
                    { ...
                    'Thresholds must satisfy:'; ...
                    ''; ...
                    '0 <= lower threshold < high threshold <= 1'}, ...
                    'Invalid thresholds');
                
                return
            end
            
            % =============================================================
            % 3. SELECT REFERENCE FOLDER
            % =============================================================
            
            referenceFolder = ...
                uigetdir( ...
                pwd, ...
                sprintf( ...
                'Select TXT reference folder for %s', ...
                componentName));
            
            if isequal(referenceFolder, 0)
                return
            end


            % =============================================================
            % 4. CHECK WHETHER SAME RESULT ALREADY EXISTS
            % =============================================================
            
            existingIndex = ...
                app.findComponentAssessmentIndex( ...
                C.ID, ...
                componentName);
            
            if ~isempty(existingIndex)
                
                previousAssessment = ...
                    app.ComponentAssessments(existingIndex);
                
                replaceChoice = ...
                    uiconfirm( ...
                    app.UIFigure, ...
                    { ...
                    sprintf( ...
                    '%s already has a "%s" reference assessment.', ...
                    C.Name, ...
                    componentName); ...
                    ''; ...
                    ['Running again will replace only this exact ' ...
                    'cluster/component result.']; ...
                    ''; ...
                    'All other component assessments will remain unchanged.'}, ...
                    'Update existing assessment', ...
                    'Options', ...
                    {'Update this result', 'Cancel'}, ...
                    'DefaultOption', ...
                    'Update this result', ...
                    'CancelOption', ...
                    'Cancel');
                
                if ~strcmp( ...
                        replaceChoice, ...
                        'Update this result')
                    
                    return
                end
                
                %#ok<NASGU>
                previousAssessment = ...
                    previousAssessment;
            end
            % =============================================================
            % CHECK 750 / 785 REGION
            % =============================================================
            
            requiredLowWN = ...
                min( ...
                [ ...
                app.RatioBaselineLeftWindow, ...
                app.Ratio750Window, ...
                app.Ratio785Window, ...
                app.RatioBaselineRightWindow]);
            
            requiredHighWN = ...
                max( ...
                [ ...
                app.RatioBaselineLeftWindow, ...
                app.Ratio750Window, ...
                app.Ratio785Window, ...
                app.RatioBaselineRightWindow]);
            
            if min(app.Wavenumber) > requiredLowWN || ...
                    max(app.Wavenumber) < requiredHighWN
                
                uialert( ...
                    app.UIFigure, ...
                    { ...
                    'The Raman axis does not fully cover the 750/785 ratio region.'; ...
                    ''; ...
                    sprintf( ...
                    'Required range: %.1f-%.1f cm^-1', ...
                    requiredLowWN, ...
                    requiredHighWN); ...
                    sprintf( ...
                    'Available range: %.1f-%.1f cm^-1', ...
                    min(app.Wavenumber), ...
                    max(app.Wavenumber))}, ...
                    '750/785 ratio unavailable');
                
                return
            end
            % =============================================================
            % 5. RUN
            % =============================================================
            
            app.UIFigure.Pointer = ...
                'watch';
            
            drawnow;
            
            try
                
                app.TextArea.Value = { ...
                    'REFERENCE-COMPONENT ASSESSMENT'; ...
                    '================================================'; ...
                    ''; ...
                    ['Cluster: ' C.Name]; ...
                    ['Component: ' componentName]; ...
                    ''; ...
                    'Loading and checking reference TXT files...'};
                
                drawnow;
                
                referenceData = ...
                    app.loadReferenceSpectraFolder( ...
                    referenceFolder);
                
                assessment = ...
                    app.calculateReferenceComponentAssessment( ...
                    clusterIndex, ...
                    componentName, ...
                    referenceData, ...
                    lowThreshold, ...
                    highThreshold);
                
                assessmentIndex = ...
                    app.storeComponentAssessment( ...
                    assessment);
                
                app.CurrentComponentAssessmentIndex = ...
                    assessmentIndex;
                
                % =============================================================
                % REBUILD LAYER-LEVEL DERIVED SUPPORT
                %
                % This rebuilds from all current non-stale assessments.
                % Rerunning the same ClusterID + ComponentName therefore replaces
                % that cluster contribution rather than appending duplicates.
                % =============================================================
                
                app.rebuildAllComponentLayerAggregates();
                
                % =============================================================
                % INDIVIDUAL CLUSTER RESULT ON AXIS 4
                % =============================================================
                
                app.displayComponentAssessmentOnAxis4( ...
                    assessmentIndex);
                
                app.displayComponentAssessmentText( ...
                    assessmentIndex);
                
                % =============================================================
                % CORRESPONDING LAYER AGGREGATE ON AXIS 2
                % =============================================================
                
                currentClusterIndex = ...
                    app.findClusterIndexByID( ...
                    assessment.ClusterID);
                
                if ~isempty(currentClusterIndex)
                    
                    currentLayerName = ...
                        app.Clusters(currentClusterIndex).Class;
                    
                    aggregateIndex = ...
                        app.findComponentLayerAggregateIndex( ...
                        currentLayerName, ...
                        assessment.ComponentName);
                    
                    if ~isempty(aggregateIndex)
                        
                        app.CurrentComponentLayerAggregateIndex = ...
                            aggregateIndex;
                        
                        app.displayComponentLayerAggregateOnAxis2( ...
                            aggregateIndex, ...
                            'all');
                    end
                end
                
                % Recreate Tree only after aggregate data are ready.
                app.populateClusterTree();
                
            catch ME
                
                uialert( ...
                    app.UIFigure, ...
                    getReport( ...
                    ME, ...
                    'extended', ...
                    'hyperlinks', ...
                    'off'), ...
                    'Reference-component assessment error');
            end
            
            app.UIFigure.Pointer = ...
                'arrow';
            
            drawnow;
            
        end
        
        
        function selectedClusterIndices = ...
                getSelectedClustersForSingleMode(app)
            
            selectedClusterIndices = [];
            
            selectedIDs = ...
                unique( ...
                double(app.SelectedClusterIDs(:)), ...
                'stable');
            
            if isempty(selectedIDs)
                
                uialert( ...
                    app.UIFigure, ...
                    { ...
                    'Select 2 or 3 active clusters in the Tree.'; ...
                    ''; ...
                    ['The selected clusters will become separate groups ' ...
                    'in single_mode.']}, ...
                    'No clusters selected');
                
                return
            end
            
            if numel(selectedIDs) < 2 || ...
                    numel(selectedIDs) > 3
                
                uialert( ...
                    app.UIFigure, ...
                    { ...
                    sprintf( ...
                    'Selected clusters: %d', ...
                    numel(selectedIDs)); ...
                    ''; ...
                    'Select exactly 2 or 3 active clusters.'}, ...
                    'Invalid group count');
                
                return
            end
            
            for selectedNumber = 1:numel(selectedIDs)
                
                clusterIndex = ...
                    app.findClusterIndexByID( ...
                    selectedIDs(selectedNumber));
                
                if isempty(clusterIndex)
                    
                    uialert( ...
                        app.UIFigure, ...
                        sprintf( ...
                        'Cluster ID %d could not be found.', ...
                        selectedIDs(selectedNumber)), ...
                        'Cluster not found');
                    
                    selectedClusterIndices = [];
                    return
                end
                
                C = ...
                    app.Clusters(clusterIndex);
                
                if ~C.Active
                    
                    uialert( ...
                        app.UIFigure, ...
                        sprintf( ...
                        '%s is no longer active.', ...
                        C.Name), ...
                        'Inactive cluster');
                    
                    selectedClusterIndices = [];
                    return
                end
                
                if isempty(C.PixelIdx)
                    
                    uialert( ...
                        app.UIFigure, ...
                        sprintf( ...
                        '%s contains no pixels.', ...
                        C.Name), ...
                        'Empty cluster');
                    
                    selectedClusterIndices = [];
                    return
                end
                
                selectedClusterIndices(end + 1, 1) = ...
                    clusterIndex; %#ok<AGROW>
            end
            
        end
        
        

        function displayComponentAssessmentOnAxis4( ...
                app, ...
                assessmentIndex)
            
            if nargin < 2 || ...
                    isempty(assessmentIndex) || ...
                    ~isfinite(assessmentIndex) || ...
                    assessmentIndex < 1 || ...
                    assessmentIndex > numel(app.ComponentAssessments)
                
                return
            end
            
            assessment = ...
                app.ComponentAssessments(assessmentIndex);
            
            assessment = ...
                app.updateComponentAssessmentStaleState( ...
                assessment);
            
            app.ComponentAssessments(assessmentIndex) = ...
                assessment;
            
            app.resetImageAxis(app.UIAxes_4);
            
            % =============================================================
            % 1. VALIDATE RATIO RESULT
            % =============================================================
            
            if ~isfield(assessment, 'RatioScoreMap') || ...
                    isempty(assessment.RatioScoreMap)
                
                text( ...
                    app.UIAxes_4, ...
                    0.5, ...
                    0.5, ...
                    'No 750/785 ratio map is available', ...
                    'Units', ...
                    'normalized', ...
                    'HorizontalAlignment', ...
                    'center');
                
                title( ...
                    app.UIAxes_4, ...
                    '750/785 ratio assessment');
                
                return
            end
            
            ratioScoreMap = ...
                assessment.RatioScoreMap;
            
            validRatioMask = ...
                isfinite(ratioScoreMap);
            
            if ~any(validRatioMask(:))
                
                text( ...
                    app.UIAxes_4, ...
                    0.5, ...
                    0.5, ...
                    { ...
                    'No valid 750/785 ratio could be calculated.'; ...
                    'The 785 denominator may be below the reliability floor.'}, ...
                    'Units', ...
                    'normalized', ...
                    'HorizontalAlignment', ...
                    'center');
                
                title( ...
                    app.UIAxes_4, ...
                    sprintf( ...
                    '%s — %s 750/785 ratio support', ...
                    assessment.ClusterName, ...
                    assessment.ComponentName), ...
                    'Interpreter', ...
                    'none');
                
                return
            end
            
            % =============================================================
            % 2. CONTINUOUS 0-1 RATIO SCORE MAP
            % =============================================================
            
            imageHandle = ...
                imagesc( ...
                app.UIAxes_4, ...
                ratioScoreMap, ...
                [0 1]);
            
            imageHandle.AlphaData = ...
                double(validRatioMask);
            
            imageHandle.AlphaDataMapping = ...
                'none';
            
            imageHandle.HitTest = ...
                'off';
            
            axis(app.UIAxes_4, 'image');
            
            app.UIAxes_4.YDir = ...
                'normal';
            
            app.UIAxes_4.Color = ...
                [0 0 0];
            
            % Continuous color scale.
            colormap( ...
                app.UIAxes_4, ...
                turbo(256));
            
            colorbarHandle = ...
                colorbar(app.UIAxes_4);
            
            colorbarHandle.Label.String = ...
                '750/785 reference-ratio compatibility score';
            
            % =============================================================
            % 3. CLUSTER BOUNDARY AND FOUR-LEVEL CONTOURS
            % =============================================================
            
            clusterMask = ...
                false(size(app.ValidMask2D));
            
            clusterIndex = ...
                app.findClusterIndexByID( ...
                assessment.ClusterID);
            
            if ~isempty(clusterIndex)
                
                clusterMask( ...
                    app.Clusters(clusterIndex).PixelIdx) = ...
                    true;
                
            else
                
                clusterMask(assessment.PixelIdx) = ...
                    true;
            end
            
            hold(app.UIAxes_4, 'on');
            
            % Selected cluster boundary.
            contour( ...
                app.UIAxes_4, ...
                clusterMask, ...
                [0.5 0.5], ...
                'w', ...
                'LineWidth', ...
                1.6, ...
                'HitTest', ...
                'off');
            
            % 0.40 boundary
            contour( ...
                app.UIAxes_4, ...
                ratioScoreMap, ...
                [ ...
                app.RatioScoreWeakThreshold, ...
                app.RatioScoreWeakThreshold], ...
                'Color', ...
                [0.85 0.85 0.85], ...
                'LineStyle', ...
                ':', ...
                'LineWidth', ...
                0.9, ...
                'HitTest', ...
                'off');
            
            % 0.60 boundary
            contour( ...
                app.UIAxes_4, ...
                ratioScoreMap, ...
                [ ...
                app.RatioScoreModerateThreshold, ...
                app.RatioScoreModerateThreshold], ...
                'Color', ...
                [1.00 1.00 1.00], ...
                'LineStyle', ...
                '--', ...
                'LineWidth', ...
                1.0, ...
                'HitTest', ...
                'off');
            
            % 0.80 boundary
            contour( ...
                app.UIAxes_4, ...
                ratioScoreMap, ...
                [ ...
                app.RatioScoreStrongThreshold, ...
                app.RatioScoreStrongThreshold], ...
                'Color', ...
                [1.00 1.00 1.00], ...
                'LineStyle', ...
                '-', ...
                'LineWidth', ...
                1.4, ...
                'HitTest', ...
                'off');
            
            hold(app.UIAxes_4, 'off');
            
            % =============================================================
            % 4. TITLE
            % =============================================================
            
            if assessment.IsStale
                
                statusLine = ...
                    'STALE: cluster pixels changed; rerun before interpretation';
                
            elseif ~isfinite(assessment.ReferenceRatioMedian)
                
                statusLine = ...
                    'Reference ratio unavailable';
                
            else
                
                statusLine = ...
                    sprintf( ...
                    'Reference 750/785 ratio = %.4g', ...
                    assessment.ReferenceRatioMedian);
            end
            
            title( ...
                app.UIAxes_4, ...
                { ...
                sprintf( ...
                '%s — %s 750/785 ratio compatibility', ...
                assessment.ClusterName, ...
                assessment.ComponentName), ...
                statusLine, ...
                ['<0.40 low | 0.40-0.60 weak | ' ...
                '0.60-0.80 moderate | 0.80-1.00 strong']}, ...
                'Interpreter', ...
                'none');
            
        end


        function safeText = makeRepresentativeGroupName( ...
                app, ...
                textIn)
            
            %#ok<INUSD>
            
            safeText = ...
                char(string(textIn));
            
            safeText = ...
                strtrim(safeText);
            
            safeText = ...
                regexprep( ...
                safeText, ...
                '[^\w\-]+', ...
                '_');
            
            safeText = ...
                regexprep( ...
                safeText, ...
                '_+', ...
                '_');
            
            safeText = ...
                regexprep( ...
                safeText, ...
                '^_+|_+$', ...
                '');
            
            if isempty(safeText)
                
                safeText = ...
                    'Cluster';
            end
            
        end
        
        
        function displayComponentAssessmentText( ...
                app, ...
                assessmentIndex)
            
            if nargin < 2 || ...
                    isempty(assessmentIndex) || ...
                    assessmentIndex < 1 || ...
                    assessmentIndex > numel(app.ComponentAssessments)
                
                return
            end
            
            assessment = ...
                app.ComponentAssessments(assessmentIndex);
            
            assessment = ...
                app.updateComponentAssessmentStaleState( ...
                assessment);
            
            app.ComponentAssessments(assessmentIndex) = ...
                assessment;
            
            M = ...
                assessment.Metrics;
            
            if assessment.IsStale
                
                statusText = ...
                    'STALE — cluster membership changed after assessment';
                
                actionText = ...
                    ['Rerun this cluster/component assessment before ' ...
                    'quantitative interpretation.'];
                
            else
                
                statusText = ...
                    'Current';
                
                actionText = ...
                    ['Use the whole-spectrum and 750/785 maps as two ' ...
                    'separate lines of spectral evidence.'];
            end
            
            textLines = { ...
                'REFERENCE-COMPONENT ASSESSMENT'; ...
                '================================================'; ...
                ''; ...
                'TARGET'; ...
                ['Component: ' assessment.ComponentName]; ...
                ['Cluster: ' assessment.ClusterName]; ...
                sprintf( ...
                'Cluster ID: %d', ...
                assessment.ClusterID); ...
                ['Assessment status: ' statusText]; ...
                ''; ...
                'REFERENCE INPUT'; ...
                ['Folder: ' assessment.ReferenceFolder]; ...
                sprintf( ...
                'Accepted TXT files: %d', ...
                assessment.ReferenceCount); ...
                sprintf( ...
                'Rejected TXT files: %d', ...
                numel(assessment.RejectedFiles)); ...
                sprintf( ...
                'Common Raman points: %d', ...
                numel(assessment.ReferenceWavenumber)); ...
                sprintf( ...
                'Common Raman range: %.2f-%.2f cm^-1', ...
                min(assessment.ReferenceWavenumber), ...
                max(assessment.ReferenceWavenumber)); ...
                'Consensus spectrum: median of normalized references'; ...
                ''; ...
                'REFERENCE CONSISTENCY'};
            
            if assessment.ReferenceCount >= 2
                
                textLines = ...
                    [ ...
                    textLines; ...
                    { ...
                    sprintf( ...
                    'Median pairwise cosine similarity: %.3f', ...
                    assessment.ReferenceConsistencyMedian); ...
                    sprintf( ...
                    'Minimum pairwise cosine similarity: %.3f', ...
                    assessment.ReferenceConsistencyMinimum)}];
                
            else
                
                textLines = ...
                    [ ...
                    textLines; ...
                    { ...
                    'Pairwise consistency: unavailable'; ...
                    'Reason: only one accepted reference spectrum'}];
            end
            
            % =============================================================
            % WHOLE-SPECTRUM MATCH
            % =============================================================
            
            textLines = ...
                [ ...
                textLines; ...
                { ...
                ''; ...
                'WHOLE-SPECTRUM REFERENCE MATCH'; ...
                sprintf( ...
                'Pixels evaluated: %d / %d (%.2f%%)', ...
                M.EvaluatedPixelCount, ...
                M.ClusterPixelCount, ...
                100 * M.EvaluatedFraction); ...
                sprintf( ...
                'Low support: %d (%.2f%%)', ...
                M.LowSupportCount, ...
                100 * M.LowSupportFraction); ...
                sprintf( ...
                'Intermediate support: %d (%.2f%%)', ...
                M.IntermediateSupportCount, ...
                100 * M.IntermediateSupportFraction); ...
                sprintf( ...
                'High support: %d (%.2f%%)', ...
                M.HighSupportCount, ...
                100 * M.HighSupportFraction); ...
                sprintf( ...
                'Mean cosine score: %.3f', ...
                M.MeanMatchScore); ...
                sprintf( ...
                'Median cosine score: %.3f', ...
                M.MedianMatchScore); ...
                sprintf( ...
                'P10-P90 cosine score: %.3f-%.3f', ...
                M.P10MatchScore, ...
                M.P90MatchScore)}];
            
            % =============================================================
            % RATIO DEFINITION
            % =============================================================
            
            textLines = ...
                [ ...
                textLines; ...
                { ...
                ''; ...
                '750 / 785 RATIO DEFINITION'; ...
                sprintf( ...
                'Numerator peak window: %.1f-%.1f cm^-1', ...
                assessment.RatioWindows.Peak750(1), ...
                assessment.RatioWindows.Peak750(2)); ...
                sprintf( ...
                'Denominator peak window: %.1f-%.1f cm^-1', ...
                assessment.RatioWindows.Peak785(1), ...
                assessment.RatioWindows.Peak785(2)); ...
                sprintf( ...
                'Left baseline anchor: %.1f-%.1f cm^-1', ...
                assessment.RatioWindows.BaselineLeft(1), ...
                assessment.RatioWindows.BaselineLeft(2)); ...
                sprintf( ...
                'Right baseline anchor: %.1f-%.1f cm^-1', ...
                assessment.RatioWindows.BaselineRight(1), ...
                assessment.RatioWindows.BaselineRight(2)); ...
                'Peak measure: positive integrated area after local linear baseline'}];
            
            % =============================================================
            % REFERENCE RATIO
            % =============================================================
            
            textLines = ...
                [ ...
                textLines; ...
                { ...
                ''; ...
                'REFERENCE 750 / 785 RATIO'; ...
                sprintf( ...
                'Reference files with valid ratio: %d / %d', ...
                nnz(isfinite( ...
                assessment.ReferenceRatios750over785)), ...
                assessment.ReferenceCount)}];
            
            if isfinite(assessment.ReferenceRatioMedian)
                
                textLines = ...
                    [ ...
                    textLines; ...
                    { ...
                    sprintf( ...
                    'Median reference ratio: %.4g', ...
                    assessment.ReferenceRatioMedian); ...
                    sprintf( ...
                    'Reference ratio P10-P90: %.4g-%.4g', ...
                    assessment.ReferenceRatioP10, ...
                    assessment.ReferenceRatioP90)}];
                
            else
                
                textLines = ...
                    [ ...
                    textLines; ...
                    { ...
                    'Median reference ratio: unavailable'; ...
                    ['Reason: the 785 peak denominator was not sufficiently ' ...
                    'above the reliability floor.']}];
            end
            
            % =============================================================
            % PIXEL RATIO SCORE
            % =============================================================
            
            textLines = ...
                [ ...
                textLines; ...
                { ...
                ''; ...
                '750 / 785 RATIO-SCORE COVERAGE'; ...
                sprintf( ...
                'Valid ratio pixels: %d / %d (%.2f%%)', ...
                M.RatioValidPixelCount, ...
                M.ClusterPixelCount, ...
                100 * M.RatioValidFraction); ...
                sprintf( ...
                'Unavailable ratio pixels: %d', ...
                M.RatioUnavailablePixelCount); ...
                ''; ...
                'RATIO COMPATIBILITY LEVELS'; ...
                sprintf( ...
                'Strong, 0.80-1.00: %d (%.2f%%)', ...
                M.RatioStrongCount, ...
                100 * M.RatioStrongFraction); ...
                sprintf( ...
                'Moderate, 0.60-0.80: %d (%.2f%%)', ...
                M.RatioModerateCount, ...
                100 * M.RatioModerateFraction); ...
                sprintf( ...
                'Weak, 0.40-0.60: %d (%.2f%%)', ...
                M.RatioWeakCount, ...
                100 * M.RatioWeakFraction); ...
                sprintf( ...
                'Low, below 0.40: %d (%.2f%%)', ...
                M.RatioLowCount, ...
                100 * M.RatioLowFraction)}];
            
            if isfinite(M.MedianPixelRatio750over785)
                
                textLines = ...
                    [ ...
                    textLines; ...
                    { ...
                    ''; ...
                    'PIXEL RATIO DISTRIBUTION'; ...
                    sprintf( ...
                    'Mean pixel ratio: %.4g', ...
                    M.MeanPixelRatio750over785); ...
                    sprintf( ...
                    'Median pixel ratio: %.4g', ...
                    M.MedianPixelRatio750over785); ...
                    sprintf( ...
                    'P10-P90 pixel ratio: %.4g-%.4g', ...
                    M.P10PixelRatio750over785, ...
                    M.P90PixelRatio750over785); ...
                    sprintf( ...
                    'Mean compatibility score: %.3f', ...
                    M.MeanRatioScore); ...
                    sprintf( ...
                    'Median compatibility score: %.3f', ...
                    M.MedianRatioScore); ...
                    sprintf( ...
                    'P10-P90 compatibility score: %.3f-%.3f', ...
                    M.P10RatioScore, ...
                    M.P90RatioScore)}];
            end
            
            % =============================================================
            % INTERPRETATION
            % =============================================================
            
            textLines = ...
                [ ...
                textLines; ...
                { ...
                ''; ...
                'INTERPRETATION'; ...
                ['Whole-spectrum cosine score describes general spectral ' ...
                'agreement with the supplied component reference.']; ...
                ['The 750/785 score describes compatibility with the ' ...
                'reference peak ratio after local baseline correction.']; ...
                ['Agreement between both maps is stronger evidence than ' ...
                'either metric alone.']; ...
                ''; ...
                'IMPORTANT LIMITATION'; ...
                ['Neither score is an absolute chemical-purity percentage.']; ...
                ['A ratio score of 0.80 means compatibility with the ' ...
                'reference ratio, not 80% mitochondrial content.']; ...
                ['Pixels with an unreliable 785 denominator are excluded ' ...
                'instead of being assigned an artificially large ratio.']; ...
                ''; ...
                'CURRENT ACTION'; ...
                actionText; ...
                ''; ...
                'STORAGE'; ...
                sprintf( ...
                'Stored key: Cluster %d + %s', ...
                assessment.ClusterID, ...
                assessment.ComponentName); ...
                ['Assessments for other clusters and components remain ' ...
                'preserved.']}];
            
            if ~isempty(assessment.RejectedFiles)
                
                textLines = ...
                    [ ...
                    textLines; ...
                    { ...
                    ''; ...
                    'REJECTED REFERENCE FILES'}];
                
                for fileNumber = 1:numel(assessment.RejectedFiles)
                    
                    textLines{end + 1} = ...
                        assessment.RejectedFiles{fileNumber}; %#ok<AGROW>
                end
            end
            
            app.TextArea.Value = ...
                textLines;
            
        end
        
        
        function assessmentIndex = ...
                findLatestComponentAssessmentForCluster( ...
                app, ...
                clusterID)
            
            assessmentIndex = [];
            
            if isempty(app.ComponentAssessments)
                return
            end
            
            matchingIndices = ...
                find( ...
                [app.ComponentAssessments.ClusterID] == ...
                clusterID);
            
            if isempty(matchingIndices)
                return
            end
            
            creationTimes = ...
                [app.ComponentAssessments(matchingIndices).CreatedAt];
            
            [~, newestPosition] = ...
                max(creationTimes);
            
            assessmentIndex = ...
                matchingIndices(newestPosition);
            
        end
        
        
        function displayComponentLayerAggregateText( ...
                app, ...
                aggregateIndex, ...
                focusLevel)
            
            % =============================================================
            % DISPLAY TEXT REPORT FOR A DERIVED COMPONENT-LAYER AGGREGATE
            %
            % focusLevel:
            %   'all'
            %   'strong'
            %   'medium'
            % =============================================================
            
            if nargin < 3 || ...
                    isempty(focusLevel)
                
                focusLevel = ...
                    'all';
            end
            
            % =============================================================
            % 1. VALIDATE AGGREGATE INDEX
            % =============================================================
            
            if isempty(aggregateIndex) || ...
                    ~isscalar(aggregateIndex) || ...
                    ~isfinite(aggregateIndex)
                
                return
            end
            
            aggregateIndex = ...
                round(double(aggregateIndex));
            
            if aggregateIndex < 1 || ...
                    aggregateIndex > numel(app.ComponentLayerAggregates)
                
                return
            end
            
            G = ...
                app.ComponentLayerAggregates(aggregateIndex);
            
            % =============================================================
            % 2. SAFE COUNTS
            % =============================================================
            
            layerPixelCount = ...
                max(0, double(G.LayerPixelCount));
            
            evaluatedPixelCount = ...
                max(0, double(G.EvaluatedPixelCount));
            
            unassessedPixelCount = ...
                max(0, double(G.UnassessedPixelCount));
            
            weakPixelCount = ...
                max(0, double(G.WeakPixelCount));
            
            mediumPixelCount = ...
                max(0, double(G.MediumPixelCount));
            
            strongPixelCount = ...
                max(0, double(G.StrongPixelCount));
            
            safeLayerPixelCount = ...
                max(1, layerPixelCount);
            
            safeEvaluatedPixelCount = ...
                max(1, evaluatedPixelCount);
            
            % =============================================================
            % 3. CLUSTER IDS
            % =============================================================
            
            layerClusterIDs = ...
                double(G.LayerClusterIDs(:).');
            
            assessedClusterIDs = ...
                double(G.AssessedClusterIDs(:).');
            
            unassessedClusterIDs = ...
                setdiff( ...
                layerClusterIDs, ...
                assessedClusterIDs, ...
                'stable');
            
            % =============================================================
            % 4. FOCUS LABEL
            % =============================================================
            
            focusLevel = ...
                lower(strtrim(char(string(focusLevel))));
            
            switch focusLevel
                
                case 'strong'
                    
                    focusDescription = ...
                        ['Strong pixels are emphasized on Axis 2. ' ...
                        'Medium pixels remain visible with reduced emphasis.'];
                    
                case 'medium'
                    
                    focusDescription = ...
                        ['Medium pixels are emphasized on Axis 2. ' ...
                        'Strong pixels remain visible with reduced emphasis.'];
                    
                otherwise
                    
                    focusLevel = ...
                        'all';
                    
                    focusDescription = ...
                        ['Axis 2 displays the complete derived layer: ' ...
                        'Unassessed, Weak, Medium, and Strong.'];
            end
            
            % =============================================================
            % 5. OPTIONAL CREATION TIME
            % =============================================================
            
            createdText = ...
                'Unavailable';
            
            if isfield(G, 'CreatedAt') && ...
                    ~isempty(G.CreatedAt)
                
                try
                    
                    createdText = ...
                        char(string(G.CreatedAt));
                    
                catch
                end
            end
            
            % =============================================================
            % 6. BUILD REPORT
            % =============================================================
            
            textLines = { ...
                'AGGREGATED COMPONENT SUPPORT'; ...
                '================================================'; ...
                ''; ...
                'IDENTITY'; ...
                ['Layer: ' char(string(G.LayerName))]; ...
                ['Reference component: ' char(string(G.ComponentName))]; ...
                ['Display focus: ' focusLevel]; ...
                ['Updated: ' createdText]; ...
                ''; ...
                'CLUSTER COVERAGE'; ...
                sprintf( ...
                'Active clusters in layer: %d', ...
                double(G.TotalClusterCount)); ...
                sprintf( ...
                'Clusters assessed with this component: %d', ...
                double(G.AssessedClusterCount)); ...
                sprintf( ...
                'Assessed cluster IDs: %s', ...
                mat2str(assessedClusterIDs)); ...
                sprintf( ...
                'Unassessed cluster IDs: %s', ...
                mat2str(unassessedClusterIDs)); ...
                ''; ...
                'LAYER PIXEL COVERAGE'; ...
                sprintf( ...
                'Total usable layer pixels: %d', ...
                layerPixelCount); ...
                sprintf( ...
                'Evaluated ratio pixels: %d (%.2f%% of layer)', ...
                evaluatedPixelCount, ...
                100 * evaluatedPixelCount / safeLayerPixelCount); ...
                sprintf( ...
                'Unassessed layer pixels: %d (%.2f%% of layer)', ...
                unassessedPixelCount, ...
                100 * unassessedPixelCount / safeLayerPixelCount); ...
                ''; ...
                'COMPOSITION AMONG EVALUATED PIXELS'; ...
                sprintf( ...
                'Strong: %d (%.2f%%)', ...
                strongPixelCount, ...
                100 * strongPixelCount / safeEvaluatedPixelCount); ...
                sprintf( ...
                'Medium: %d (%.2f%%)', ...
                mediumPixelCount, ...
                100 * mediumPixelCount / safeEvaluatedPixelCount); ...
                sprintf( ...
                'Weak: %d (%.2f%%)', ...
                weakPixelCount, ...
                100 * weakPixelCount / safeEvaluatedPixelCount); ...
                ''; ...
                'COMPOSITION RELATIVE TO WHOLE LAYER'; ...
                sprintf( ...
                'Strong: %.2f%% of layer', ...
                100 * strongPixelCount / safeLayerPixelCount); ...
                sprintf( ...
                'Medium: %.2f%% of layer', ...
                100 * mediumPixelCount / safeLayerPixelCount); ...
                sprintf( ...
                'Weak: %.2f%% of layer', ...
                100 * weakPixelCount / safeLayerPixelCount); ...
                ''; ...
                'RATIO-SCORE LEVELS'; ...
                sprintf( ...
                'Strong: score >= %.2f', ...
                app.RatioScoreStrongThreshold); ...
                sprintf( ...
                'Medium: %.2f <= score < %.2f', ...
                app.RatioScoreModerateThreshold, ...
                app.RatioScoreStrongThreshold); ...
                sprintf( ...
                'Weak: score < %.2f', ...
                app.RatioScoreModerateThreshold); ...
                ''; ...
                'CURRENT DISPLAY'; ...
                focusDescription; ...
                ''; ...
                'UPDATE BEHAVIOR'; ...
                ['Running another cluster in the same layer with the same ' ...
                'component name rebuilds this aggregate.']; ...
                ['Rerunning the same ClusterID and ComponentName replaces ' ...
                'that cluster contribution before rebuilding.']; ...
                ''; ...
                'IMPORTANT'; ...
                ['Unassessed pixels are kept separate from Weak pixels. ' ...
                'They have not yet received a valid ratio assessment.']; ...
                ['This is a derived component-support layer only. It does not ' ...
                'modify app.Clusters or ClusterLabelMap.']};
            
            app.TextArea.Value = ...
                textLines;
            
        end
        
        
        function assessmentIndex = ...
                chooseComponentAssessmentForCluster( ...
                app, ...
                clusterID)
            
            assessmentIndex = [];
            
            matchingIndices = ...
                find( ...
                [app.ComponentAssessments.ClusterID] == ...
                clusterID);
            
            if isempty(matchingIndices)
                return
            end
            
            if numel(matchingIndices) == 1
                
                assessmentIndex = ...
                    matchingIndices(1);
                
                return
            end
            
            optionNames = ...
                cell(numel(matchingIndices), 1);
            
            for optionNumber = 1:numel(matchingIndices)
                
                A = ...
                    app.ComponentAssessments( ...
                    matchingIndices(optionNumber));
                
                A = ...
                    app.updateComponentAssessmentStaleState(A);
                
                if A.IsStale
                    staleText = ' | STALE';
                else
                    staleText = '';
                end
                
                optionNames{optionNumber} = ...
                    sprintf( ...
                    '%s | high support %.1f%%%s', ...
                    A.ComponentName, ...
                    100 * A.Metrics.HighSupportFraction, ...
                    staleText);
            end
            
            [selection, accepted] = ...
                listdlg( ...
                'PromptString', ...
                'Choose a component assessment to display:', ...
                'SelectionMode', ...
                'single', ...
                'ListString', ...
                optionNames, ...
                'ListSize', ...
                [420 230]);
            
            if ~accepted || ...
                    isempty(selection)
                
                return
            end
            
            assessmentIndex = ...
                matchingIndices(selection);
            
        end
        
        
        function saveSelectedClusterMapEvidence( ...
                app, ...
                outputPath)
            
            evidenceFigure = ...
                figure( ...
                'Visible', ...
                'off', ...
                'Color', ...
                'w', ...
                'Position', ...
                [100 100 900 760]);
            
            cleanupObject = ...
                onCleanup( ...
                @() delete(evidenceFigure)); %#ok<NASGU>
            
            evidenceAxis = ...
                axes( ...
                evidenceFigure);
            
            imagesc( ...
                evidenceAxis, ...
                app.ClusterLabelMap);
            
            axis(evidenceAxis, 'image');
            
            evidenceAxis.YDir = ...
                'normal';
            
            evidenceAxis.XTick = [];
            evidenceAxis.YTick = [];
            
            colormap( ...
                evidenceAxis, ...
                lines(max(2, app.ClusterK)));
            
            clim( ...
                evidenceAxis, ...
                [0.5 app.ClusterK + 0.5]);
            
            title( ...
                evidenceAxis, ...
                { ...
                app.CurrentScanName, ...
                sprintf( ...
                ['Selected cluster map | PCs=%d | ARI=%.3f | ' ...
                'seed=%d'], ...
                app.NumPCUsed, ...
                app.ClusterOverallMedianARI, ...
                app.ClusterSelectedSeed)}, ...
                'Interpreter', ...
                'none');
            
            exportgraphics( ...
                evidenceFigure, ...
                outputPath, ...
                'Resolution', ...
                300);
            
        end
        
        
        function savePCMapAgreementEvidence( ...
                app, ...
                outputPath)
            
            agreement = ...
                double(app.PCASelectionMapAgreement);
            
            if isempty(agreement)
                return
            end
            
            pcValues = ...
                double([app.PCASelectionResults.PCCount]);
            
            evidenceFigure = ...
                figure( ...
                'Visible', ...
                'off', ...
                'Color', ...
                'w', ...
                'Position', ...
                [100 100 900 800]);
            
            cleanupObject = ...
                onCleanup( ...
                @() delete(evidenceFigure)); %#ok<NASGU>
            
            evidenceAxis = ...
                axes( ...
                evidenceFigure);
            
            imagesc( ...
                evidenceAxis, ...
                agreement, ...
                [0 1]);
            
            axis(evidenceAxis, 'image');
            
            evidenceAxis.XTick = ...
                1:numel(pcValues);
            
            evidenceAxis.YTick = ...
                1:numel(pcValues);
            
            evidenceAxis.XTickLabel = ...
                string(pcValues);
            
            evidenceAxis.YTickLabel = ...
                string(pcValues);
            
            xlabel(evidenceAxis, 'PC candidate');
            ylabel(evidenceAxis, 'PC candidate');
            
            colormap( ...
                evidenceAxis, ...
                parula(256));
            
            colorbar(evidenceAxis);
            
            title( ...
                evidenceAxis, ...
                { ...
                app.CurrentScanName, ...
                'ARI agreement between candidate medoid maps'}, ...
                'Interpreter', ...
                'none');
            
            exportgraphics( ...
                evidenceFigure, ...
                outputPath, ...
                'Resolution', ...
                300);
            
        end
        
        
        function loadSingleExtractSamples(app)
            
            % =============================================================
            % LOAD ALL CLUSTER PACKAGES FROM SELECTED SAMPLE(S)
            %
            % One sample may contain MULTIPLE cluster MAT files:
            %
            % <ExtractionRoot>\
            %     <SampleName>\
            %         cluster_pixel_packages\
            %             run_YYYYMMDD_HHMMSS_mmm\
            %                 01_cluster_ID_1_Cluster_1.mat
            %                 02_cluster_ID_2_Cluster_2.mat
            %                 03_cluster_ID_5_Cluster_5.mat
            %
            % Every cluster MAT becomes one SingleExtractSamples record.
            %
            % This allows:
            %
            % sample
            %   -> cluster 1 -> representatives + QC
            %   -> cluster 2 -> representatives + QC
            %   -> cluster 3 -> representatives + QC
            %
            % =============================================================
            
            
            % =============================================================
            % 1. ROOTS
            % =============================================================
            
            extractionRoot = ...
                char(string(app.SingleExtractExtractionRoot));
            
            readableRoot = ...
                char(string(app.SingleExtractReadableRoot));
            
            
            if isempty(extractionRoot)
                
                error( ...
                    'single_extract:ExtractionRootEmpty', ...
                    'SingleExtractExtractionRoot is empty.');
                
            end
            
            
            if ~isfolder(extractionRoot)
                
                error( ...
                    'single_extract:ExtractionRootMissing', ...
                    sprintf( ...
                    'Extraction folder does not exist:\n\n%s', ...
                    extractionRoot));
                
            end
            
            
            if isempty(readableRoot)
                
                error( ...
                    'single_extract:ReadableRootEmpty', ...
                    'SingleExtractReadableRoot is empty.');
                
            end
            
            
            if ~isfolder(readableRoot)
                
                error( ...
                    'single_extract:ReadableRootMissing', ...
                    sprintf( ...
                    'READABLE Raman folder does not exist:\n\n%s', ...
                    readableRoot));
                
            end
            
            
            % =============================================================
            % 2. FIND SAMPLE FOLDERS
            % =============================================================
            
            folderEntries = ...
                dir(extractionRoot);
            
            folderEntries = ...
                folderEntries([folderEntries.isdir]);
            
            folderNames = ...
                string({folderEntries.name});
            
            folderEntries = ...
                folderEntries( ...
                folderNames ~= "." & ...
                folderNames ~= "..");
            
            
            % =============================================================
            % 3. KEEP ONLY SAMPLE FOLDERS WITH CLUSTER RUNS
            % =============================================================
            
            keepMask = ...
                false(1, numel(folderEntries));
            
            
            for folderNumber = 1:numel(folderEntries)
                
                sampleFolder = ...
                    fullfile( ...
                    folderEntries(folderNumber).folder, ...
                    folderEntries(folderNumber).name);
                
                packageRoot = ...
                    fullfile( ...
                    sampleFolder, ...
                    'cluster_pixel_packages');
                
                if ~isfolder(packageRoot)
                    continue
                end
                
                runEntries = ...
                    dir( ...
                    fullfile( ...
                    packageRoot, ...
                    'run_*'));
                
                runEntries = ...
                    runEntries([runEntries.isdir]);
                
                keepMask(folderNumber) = ...
                    ~isempty(runEntries);
                
            end
            
            
            folderEntries = ...
                folderEntries(keepMask);
            
            
            if isempty(folderEntries)
                
                error( ...
                    'single_extract:NoClusterPackages', ...
                    sprintf([ ...
                    'No saved cluster package was found inside:\n\n%s\n\n' ...
                    'Expected:\n' ...
                    '<SampleName>\\cluster_pixel_packages\\run_*'], ...
                    extractionRoot));
                
            end
            
            
            % =============================================================
            % 4. SORT SAMPLE NAMES
            % =============================================================
            
            sampleNames = ...
                {folderEntries.name};
            
            [sampleNames, sortOrder] = ...
                sort(sampleNames);
            
            folderEntries = ...
                folderEntries(sortOrder);
            
            
            % =============================================================
            % 5. USER SELECTS SAMPLE(S)
            %
            % Selecting ONE sample will now load ALL clusters from that sample.
            % =============================================================
            
            [selectedIndices, ok] = ...
                listdlg( ...
                'PromptString', ...
                { ...
                'Choose scan / sample:'; ...
                ''; ...
                'All cluster packages from the latest run will be loaded.'}, ...
                'SelectionMode', ...
                'multiple', ...
                'ListString', ...
                sampleNames, ...
                'ListSize', ...
                [440 420]);
            
            
            if ~ok || isempty(selectedIndices)
                return
            end
            
            
            % =============================================================
            % 6. OUTPUT STRUCTURE
            % =============================================================
            
            loadedSamples = ...
                app.SingleExtractSamples([]);
            
            warningLines = {};
            
            
            % =============================================================
            % 7. PROCESS EACH SELECTED SAMPLE
            % =============================================================
            
            for selectionNumber = 1:numel(selectedIndices)
                
                entryIndex = ...
                    selectedIndices(selectionNumber);
                
                sampleName = ...
                    folderEntries(entryIndex).name;
                
                sampleFolder = ...
                    fullfile( ...
                    extractionRoot, ...
                    sampleName);
                
                
                try
                    
                    % =====================================================
                    % 7A. LATEST CLUSTER RUN
                    % =====================================================
                    
                    runFolder = ...
                        app.findLatestClusterPackageRun( ...
                        sampleFolder);
                    
                    
                    if isempty(runFolder)
                        
                        error( ...
                            'single_extract:ClusterRunMissing', ...
                            sprintf( ...
                            'No cluster package run exists for sample:\n\n%s', ...
                            sampleName));
                        
                    end
                    
                    
                    % =====================================================
                    % 7B. FIND ALL CLUSTER MAT FILES
                    % =====================================================
                    
                    matFiles = ...
                        dir( ...
                        fullfile( ...
                        runFolder, ...
                        '*_cluster_ID_*.mat'));
                    
                    
                    if isempty(matFiles)
                        
                        % Compatibility fallback:
                        % inspect all MAT files.
                        
                        matFiles = ...
                            dir( ...
                            fullfile( ...
                            runFolder, ...
                            '*.mat'));
                        
                    end
                    
                    
                    if isempty(matFiles)
                        
                        error( ...
                            'single_extract:ClusterPackageMissing', ...
                            sprintf( ...
                            'No cluster package MAT exists inside:\n\n%s', ...
                            runFolder));
                        
                    end
                    
                    
                    % Deterministic file order.
                    [~, fileOrder] = ...
                        sort( ...
                        lower(string({matFiles.name})));
                    
                    matFiles = ...
                        matFiles(fileOrder);
                    
                    
                    % =====================================================
                    % 7C. RESOLVE READABLE RAMAN FILE ONCE PER SAMPLE
                    % =====================================================
                    
                    readableFolder = ...
                        fullfile( ...
                        readableRoot, ...
                        sampleName);
                    
                    readableMATPath = ...
                        fullfile( ...
                        readableFolder, ...
                        [sampleName '_RamanData.mat']);
                    
                    
                    if ~isfile(readableMATPath)
                        
                        error( ...
                            'single_extract:ReadableMATMissing', ...
                            sprintf([ ...
                            'Matching READABLE Raman MAT was not found.\n\n' ...
                            'Sample: %s\n' ...
                            'Expected:\n%s'], ...
                            sampleName, ...
                            readableMATPath));
                        
                    end
                    
                    
                    % =====================================================
                    % 7D. LOAD READABLE CUBE ONCE
                    % =====================================================
                    
                    [readableCube, readableWN] = ...
                        app.loadReadableRamanCube( ...
                        readableMATPath);
                    
                    
                    bandMap = ...
                        app.buildSingleExtractBandMap( ...
                        readableCube, ...
                        readableWN);
                    
                    
                    tracePNGPath = ...
                        app.resolveSampleTracePNG( ...
                        sampleName);
                    
                    
                    traceImage = [];
                    
                    if ~isempty(tracePNGPath) && ...
                            isfile(tracePNGPath)
                        
                        try
                            
                            traceImage = ...
                                imread(tracePNGPath);
                            
                        catch
                            
                            traceImage = [];
                            
                        end
                        
                    end
                    
                    
                    % =====================================================
                    % 7E. LOAD EVERY CLUSTER PACKAGE
                    % =====================================================
                    
                    for clusterFileNumber = 1:numel(matFiles)
                        
                        clusterMATPath = ...
                            fullfile( ...
                            matFiles(clusterFileNumber).folder, ...
                            matFiles(clusterFileNumber).name);
                        
                        
                        try
                            
                            loadedData = ...
                                load( ...
                                clusterMATPath, ...
                                'ClusterPackage');
                            
                            
                            if ~isfield(loadedData, 'ClusterPackage')
                                
                                error( ...
                                    'Variable ClusterPackage is missing.');
                                
                            end
                            
                            
                            P = ...
                                loadedData.ClusterPackage;
                            
                            
                            % =================================================
                            % VALIDATE PACKAGE
                            % =================================================
                            
                            if ~isfield(P, 'Wavenumber') || ...
                                    isempty(P.Wavenumber)
                                
                                error( ...
                                    'ClusterPackage.Wavenumber is missing.');
                                
                            end
                            
                            
                            if ~isfield(P, 'Map') || ...
                                    ~isstruct(P.Map) || ...
                                    ~isfield(P.Map, 'ClusterMask2D')
                                
                                error( ...
                                    'ClusterPackage.Map.ClusterMask2D is missing.');
                                
                            end
                            
                            
                            if ~isfield(P, 'Spectra') || ...
                                    ~isstruct(P.Spectra) || ...
                                    ~isfield(P.Spectra, 'Pretreated')
                                
                                error( ...
                                    'ClusterPackage.Spectra.Pretreated is missing.');
                                
                            end
                            
                            
                            if ~isfield(P, 'Pixels') || ...
                                    ~isstruct(P.Pixels) || ...
                                    ~isfield(P.Pixels, 'PixelIdx')
                                
                                error( ...
                                    'ClusterPackage.Pixels.PixelIdx is missing.');
                                
                            end
                            
                            
                            pretreatedSpectra = ...
                                double(P.Spectra.Pretreated);
                            
                            
                            if isempty(pretreatedSpectra)
                                
                                error( ...
                                    'ClusterPackage contains no pretreated spectra.');
                                
                            end
                            
                            
                            pixelCount = ...
                                size(pretreatedSpectra, 1);
                            
                            
                            % =================================================
                            % CLUSTER IDENTITY
                            % =================================================
                            
                            clusterID = ...
                                clusterFileNumber;
                            
                            clusterName = ...
                                sprintf( ...
                                'Cluster %d', ...
                                clusterID);
                            
                            clusterClass = ...
                                'Unassigned';
                            
                            
                            if isfield(P, 'Cluster') && ...
                                    isstruct(P.Cluster)
                                
                                if isfield(P.Cluster, 'ID') && ...
                                        ~isempty(P.Cluster.ID)
                                    
                                    clusterID = ...
                                        double(P.Cluster.ID);
                                    
                                end
                                
                                
                                if isfield(P.Cluster, 'Name') && ...
                                        ~isempty(P.Cluster.Name)
                                    
                                    clusterName = ...
                                        char(string(P.Cluster.Name));
                                    
                                end
                                
                                
                                if isfield(P.Cluster, 'Class') && ...
                                        ~isempty(P.Cluster.Class)
                                    
                                    clusterClass = ...
                                        char(string(P.Cluster.Class));
                                    
                                end
                                
                            end
                            
                            
                            % =================================================
                            % SPATIAL CHECK
                            % =================================================
                            
                            clusterMask = ...
                                logical(P.Map.ClusterMask2D);
                            
                            
                            if ~isequal( ...
                                    size(clusterMask), ...
                                    size(bandMap))
                                
                                error( ...
                                    sprintf([ ...
                                    'Cluster mask and READABLE map differ.\n' ...
                                    'Cluster mask: %s\n' ...
                                    'Band map: %s'], ...
                                    mat2str(size(clusterMask)), ...
                                    mat2str(size(bandMap))));
                                
                            end
                            
                            
                            % =================================================
                            % IMPORTANT
                            %
                            % R.Name must be UNIQUE because downstream Generate
                            % writes one MAT per loaded record.
                            %
                            % We therefore retain sample identity plus cluster ID.
                            % =================================================
                            
                            recordName = ...
                                sprintf( ...
                                '%s__C%02d_%s', ...
                                sampleName, ...
                                round(clusterID), ...
                                app.makeSafeFileName(clusterName));
                            
                            
                            % =================================================
                            % BUILD RECORD
                            % =================================================
                            
                            R = struct();
                            
                            R.Name = ...
                                recordName;
                            
                            R.ExtractionFolder = ...
                                sampleFolder;
                            
                            R.RunFolder = ...
                                runFolder;
                            
                            R.ClusterMATPath = ...
                                clusterMATPath;
                            
                            R.TracePNGPath = ...
                                tracePNGPath;
                            
                            R.ReadableMATPath = ...
                                readableMATPath;
                            
                            R.ClusterPackage = ...
                                P;
                            
                            R.TraceImage = ...
                                traceImage;
                            
                            R.BandMap = ...
                                bandMap;
                            
                            R.ReadableWavenumber = ...
                                double(readableWN(:));
                            
                            R.PixelCount = ...
                                pixelCount;
                            
                            
                            loadedSamples(end + 1) = ...
                                R; %#ok<AGROW>
                            
                            
                        catch clusterError
                            
                            warningLines{end + 1, 1} = ...
                                sprintf( ...
                                '%s | %s: %s', ...
                                sampleName, ...
                                matFiles(clusterFileNumber).name, ...
                                clusterError.message); %#ok<AGROW>
                            
                        end
                        
                    end
                    
                    
                    clear readableCube
                    
                    
                catch sampleError
                    
                    warningLines{end + 1, 1} = ...
                        sprintf( ...
                        '%s: %s', ...
                        sampleName, ...
                        sampleError.message); %#ok<AGROW>
                    
                end
                
            end
            
            
            % =============================================================
            % 8. REQUIRE AT LEAST ONE CLUSTER
            % =============================================================
            
            if isempty(loadedSamples)
                
                if isempty(warningLines)
                    
                    warningText = ...
                        'No cluster could be loaded.';
                    
                else
                    
                    warningText = ...
                        strjoin( ...
                        warningLines, ...
                        newline);
                    
                end
                
                
                error( ...
                    'single_extract:NoValidSamplesLoaded', ...
                    sprintf([ ...
                    'No valid cluster package could be loaded.\n\n%s'], ...
                    warningText));
                
            end
            
            
            % =============================================================
            % 9. COMMIT
            % =============================================================
            
            app.SingleExtractSamples = ...
                loadedSamples;
            
            app.SingleExtractLoaded = ...
                true;
            
            app.SingleExtractSelectedSampleIndex = ...
                1;
            
            app.SingleExtractGroups = ...
                app.SingleExtractGroups([]);
            
            app.SingleExtractLastResults = ...
                struct([]);
            
            app.SingleExtractLastOutputInfo = ...
                struct([]);
            
            
            % =============================================================
            % 10. TREE
            % =============================================================
            
            app.populateSingleExtractTree();
            
            
            sampleNodes = ...
                app.Tree2.Children;
            
            
            if ~isempty(sampleNodes)
                
                app.Tree2.SelectedNodes = ...
                    sampleNodes(1);
                
            end
            
            
            app.displaySelectedSingleExtractSample();
            
            
            % =============================================================
            % 11. REPORT
            % =============================================================
            
            reportLines = { ...
                'CLUSTER PACKAGES LOADED'; ...
                '================================================'; ...
                ''; ...
                sprintf( ...
                'Selected samples: %d', ...
                numel(selectedIndices)); ...
                sprintf( ...
                'Clusters loaded: %d', ...
                numel(loadedSamples)); ...
                ''};
            
            
            for i = 1:numel(loadedSamples)
                
                P = ...
                    loadedSamples(i).ClusterPackage;
                
                clusterID = ...
                    NaN;
                
                clusterName = ...
                    'Cluster';
                
                clusterClass = ...
                    '';
                
                
                if isfield(P, 'Cluster') && ...
                        isstruct(P.Cluster)
                    
                    if isfield(P.Cluster, 'ID')
                        clusterID = double(P.Cluster.ID);
                    end
                    
                    if isfield(P.Cluster, 'Name')
                        clusterName = char(string(P.Cluster.Name));
                    end
                    
                    if isfield(P.Cluster, 'Class')
                        clusterClass = char(string(P.Cluster.Class));
                    end
                    
                end
                
                
                reportLines{end + 1, 1} = ...
                    sprintf( ...
                    'Cluster %g | %s | %s | pixels=%d', ...
                    clusterID, ...
                    clusterName, ...
                    clusterClass, ...
                    loadedSamples(i).PixelCount); %#ok<AGROW>
                
            end
            
            
            if ~isempty(warningLines)
                
                reportLines = ...
                    [ ...
                    reportLines; ...
                    { ...
                    ''; ...
                    'SKIPPED / WARNING'}; ...
                    warningLines];
                
            end
            
            
            reportLines = ...
                [ ...
                reportLines; ...
                { ...
                ''; ...
                'READY'; ...
                'Generate will process each cluster independently.'}];
            
            
            app.TextArea_2.Value = ...
                reportLines;
            
            
            drawnow;
            
        end
        
        
        function runCellBackgroundPixelAssessment(app)
            
            % =============================================================
            % 1. VALIDATE CLUSTER RESULT
            % =============================================================
            
            if ~app.ClusterReady || ...
                    isempty(app.Clusters)
                
                uialert( ...
                    app.UIFigure, ...
                    ['Run Cluster segmentation before starting ' ...
                    'pixel assessment.'], ...
                    'Cluster result required');
                
                return
            end
            
            activeIndices = ...
                find([app.Clusters.Active]);
            
            if isempty(activeIndices)
                
                uialert( ...
                    app.UIFigure, ...
                    'No active cluster remains for pixel assessment.', ...
                    'No active clusters');
                
                return
            end
            
            % =============================================================
            % 2. EXISTING CELL-BACKGROUND RESULT
            %
            % This section does not alter ComponentAssessments.
            % =============================================================
            
            if app.PixelAssessmentReady || ...
                    ~isempty(app.ReferenceROIs)
                
                choice = ...
                    uiconfirm( ...
                    app.UIFigure, ...
                    { ...
                    'Cell-Background assessment data already exist.'; ...
                    ''; ...
                    'Choose how to continue.'; ...
                    ''; ...
                    ['Reference-component assessments for individual clusters ' ...
                    'will not be changed.']}, ...
                    'Rerun Cell-Background assessment', ...
                    'Options', ...
                    { ...
                    'Start new assessment', ...
                    'Continue existing ROIs', ...
                    'Cancel'}, ...
                    'DefaultOption', ...
                    'Start new assessment', ...
                    'CancelOption', ...
                    'Cancel');
                
                if strcmp(choice, 'Cancel')
                    return
                end
                
                if strcmp(choice, 'Start new assessment')
                    
                    app.resetPixelAssessmentState();
                    
                elseif strcmp(choice, 'Continue existing ROIs')
                    
                    app.PixelAssessmentReady = false;
                    app.PixelAssessmentConfirmed = false;
                    
                    app.BalancedTrainingPixelIdx = [];
                    app.TrainingLabels = [];
                    app.TrainingWeights = [];
                    app.TrainingROIIDs = [];
                    
                    app.PixelModel = [];
                    app.PixelModelType = '';
                    
                    app.PixelFeatureNames = {};
                    app.PixelFeatureMu = [];
                    app.PixelFeatureSigma = [];
                    
                    app.CellMembershipMap = [];
                    app.PixelUncertaintyMap = [];
                    app.PixelReliabilityMap = [];
                    
                    app.FinalCellMask2D = logical.empty;
                    app.FinalBackgroundMask2D = logical.empty;
                    app.FinalUncertainMask2D = logical.empty;
                    app.FinalClassMap = [];
                    
                    app.PixelAssessmentMetrics = struct();
                end
            end
            
            % =============================================================
            % 3. RUN EXISTING CELL-BACKGROUND WORKFLOW
            % =============================================================
            
            app.UIFigure.Pointer = ...
                'arrow';
            
            drawnow;
            
            try
                
                accepted = ...
                    app.collectPixelReferenceROIs();
                
                if ~accepted
                    return
                end
                
                app.UIFigure.Pointer = ...
                    'watch';
                
                drawnow;
                
                app.cleanPixelReferenceROIs();
                
                app.buildBalancedReferenceTrainingSet();
                
                app.trainPixelMembershipModel();
                
                app.buildFinalPixelMasks();
                
                app.computeClusterPixelEvidence();
                
                app.PixelAssessmentReady = ...
                    true;
                
                app.PixelAssessmentConfirmed = ...
                    false;
                
                app.populateClusterTree();
                
                app.displayPixelAssessmentResults();
                
                app.updatePixelAssessmentSummary();
                
            catch ME
                
                uialert( ...
                    app.UIFigure, ...
                    getReport( ...
                    ME, ...
                    'extended', ...
                    'hyperlinks', ...
                    'off'), ...
                    'Cell-Background assessment error');
            end
            
            app.UIFigure.Pointer = ...
                'arrow';
            
            drawnow;
            
        end
        
        
        function displayPixelAssessmentResults(app)
            
            if ~app.PixelAssessmentReady
                return
            end
            
            % =============================================================
            % AXIS 2
            % THREE-REGION CLASSIFICATION + GROUND-TRUTH OUTLINES
            % =============================================================
            
            app.resetImageAxis(app.UIAxes_2);
            
            % -------------------------------------------------------------
            % 1. Grayscale Raman preview base
            % -------------------------------------------------------------
            
            baseHandle = ...
                imagesc( ...
                app.UIAxes_2, ...
                app.PreviewMap);
            
            baseHandle.HitTest = ...
                'off';
            
            axis(app.UIAxes_2, 'image');
            
            app.UIAxes_2.YDir = ...
                'normal';
            
            app.UIAxes_2.Color = ...
                [0 0 0];
            
            colormap(app.UIAxes_2, gray(256));
            
            traceValues = ...
                app.PreviewMap( ...
                app.ValidMask2D & ...
                isfinite(app.PreviewMap));
            
            if ~isempty(traceValues)
                
                lowValue = ...
                    prctile(traceValues, 2);
                
                highValue = ...
                    prctile(traceValues, 98);
                
                if isfinite(lowValue) && ...
                        isfinite(highValue) && ...
                        lowValue < highValue
                    
                    clim( ...
                        app.UIAxes_2, ...
                        [lowValue highValue]);
                end
            end
            
            hold(app.UIAxes_2, 'on');
            
            % -------------------------------------------------------------
            % 2. Build explicit three-class RGB overlay
            % -------------------------------------------------------------
            
            [ny, nx] = ...
                size(app.FinalClassMap);
            
            classRGB = ...
                zeros(ny, nx, 3);
            
            classAlpha = ...
                zeros(ny, nx);
            
            backgroundColor = ...
                [0.12 0.48 1.00];
            
            intermediateColor = ...
                [1.00 0.70 0.05];
            
            cellColor = ...
                [0.10 0.90 0.25];
            
            masks = { ...
                app.FinalBackgroundMask2D, ...
                app.FinalUncertainMask2D, ...
                app.FinalCellMask2D};
            
            colors = { ...
                backgroundColor, ...
                intermediateColor, ...
                cellColor};
            
            for classNumber = 1:3
                
                currentMask = ...
                    logical(masks{classNumber});
                
                currentColor = ...
                    colors{classNumber};
                
                for channel = 1:3
                    
                    plane = ...
                        classRGB(:, :, channel);
                    
                    plane(currentMask) = ...
                        currentColor(channel);
                    
                    classRGB(:, :, channel) = ...
                        plane;
                end
                
                classAlpha(currentMask) = ...
                    0.62;
            end
            
            overlayHandle = ...
                image( ...
                app.UIAxes_2, ...
                classRGB);
            
            overlayHandle.AlphaData = ...
                classAlpha;
            
            overlayHandle.AlphaDataMapping = ...
                'none';
            
            overlayHandle.HitTest = ...
                'off';
            
            % -------------------------------------------------------------
            % 3. Ground-truth outlines
            % -------------------------------------------------------------
            
            if ~isempty(app.BackgroundReferenceMask2D) && ...
                    any(app.BackgroundReferenceMask2D(:))
                
                contour( ...
                    app.UIAxes_2, ...
                    app.BackgroundReferenceMask2D, ...
                    [0.5 0.5], ...
                    'Color', ...
                    [0.10 0.85 1.00], ...
                    'LineWidth', ...
                    1.8, ...
                    'LineStyle', ...
                    '--', ...
                    'HitTest', ...
                    'off');
            end
            
            if ~isempty(app.CellReferenceMask2D) && ...
                    any(app.CellReferenceMask2D(:))
                
                contour( ...
                    app.UIAxes_2, ...
                    app.CellReferenceMask2D, ...
                    [0.5 0.5], ...
                    'Color', ...
                    [0.20 1.00 0.35], ...
                    'LineWidth', ...
                    1.8, ...
                    'LineStyle', ...
                    '-', ...
                    'HitTest', ...
                    'off');
            end
            
            hold(app.UIAxes_2, 'off');
            
            title( ...
                app.UIAxes_2, ...
                { ...
                'Ground-truth-supported pixel classification', ...
                sprintf( ...
                'Blue: Pcell <= %.2f | Yellow: intermediate | Green: Pcell >= %.2f', ...
                app.PixelBackgroundThreshold, ...
                app.PixelCellThreshold), ...
                'Dashed cyan = Background GT | Solid green = Cell GT'}, ...
                'Interpreter', ...
                'none');
            
            % =============================================================
            % AXIS 4
            % CLUSTER COMPOSITION OR SELECTED-CLUSTER DISTRIBUTION
            % =============================================================
            
            % =============================================================
            % AXIS 4
            %
            % Preserve an active component assessment when available.
            % Otherwise display the Cell-Background cluster evidence.
            % =============================================================
            
            selectedIDs = ...
                app.SelectedClusterIDs;
            
            componentIndex = [];
            
            if numel(selectedIDs) == 1
                
                componentIndex = ...
                    app.findLatestComponentAssessmentForCluster( ...
                    selectedIDs(1));
            end
            
            if ~isempty(componentIndex)
                
                app.CurrentComponentAssessmentIndex = ...
                    componentIndex;
                
                app.displayComponentAssessmentOnAxis4( ...
                    componentIndex);
                
            else
                
                app.displayClusterEvidenceOnAxis4();
            end
            
        end
        
        function updatePixelAssessmentSummary(app)
            
            backgroundROIIndices = ...
                find(strcmp( ...
                {app.ReferenceROIs.Class}, ...
                'Background'));
            
            cellROIIndices = ...
                find(strcmp( ...
                {app.ReferenceROIs.Class}, ...
                'Cell'));
            
            acceptedBackground = ...
                backgroundROIIndices( ...
                [app.ReferenceROIs(backgroundROIIndices).UsablePixelCount] >= ...
                app.MinUsablePixelsPerROI);
            
            acceptedCell = ...
                cellROIIndices( ...
                [app.ReferenceROIs(cellROIIndices).UsablePixelCount] >= ...
                app.MinUsablePixelsPerROI);
            
            backgroundUsable = ...
                sum( ...
                [app.ReferenceROIs(backgroundROIIndices).UsablePixelCount]);
            
            cellUsable = ...
                sum( ...
                [app.ReferenceROIs(cellROIIndices).UsablePixelCount]);
            
            numberBackgroundTraining = ...
                nnz(app.TrainingLabels == 0);
            
            numberCellTraining = ...
                nnz(app.TrainingLabels == 1);
            
            validCount = ...
                max(1, nnz(app.ValidMask2D));
            
            validation = struct();
            
            if isfield(app.PixelAssessmentMetrics, 'Validation')
                
                validation = ...
                    app.PixelAssessmentMetrics.Validation;
            end
            
            textLines = { ...
                'PIXEL ASSESSMENT COMPLETED'; ...
                '================================'; ...
                ''; ...
                ['Scan: ' app.CurrentScanName]; ...
                ['Model: ' app.PixelModelType]; ...
                ''; ...
                'REFERENCE ROI QC'; ...
                sprintf( ...
                'Background ROIs: %d selected | %d accepted', ...
                numel(backgroundROIIndices), ...
                numel(acceptedBackground)); ...
                sprintf( ...
                'Cell ROIs: %d selected | %d accepted', ...
                numel(cellROIIndices), ...
                numel(acceptedCell)); ...
                sprintf( ...
                'Background usable pixels after QC: %d', ...
                backgroundUsable); ...
                sprintf( ...
                'Cell usable pixels after QC: %d', ...
                cellUsable); ...
                ''; ...
                'BALANCED TRAINING SET'; ...
                sprintf( ...
                'Background training pixels: %d', ...
                numberBackgroundTraining); ...
                sprintf( ...
                'Cell training pixels: %d', ...
                numberCellTraining); ...
                sprintf( ...
                'Features used: %d', ...
                numel(app.PixelFeatureNames)); ...
                ''; ...
                'FINAL PIXEL CLASSES'; ...
                sprintf( ...
                'Confident background: %d (%.2f%%)', ...
                nnz(app.FinalBackgroundMask2D), ...
                100 * nnz(app.FinalBackgroundMask2D) / validCount); ...
                sprintf( ...
                'Confident cell: %d (%.2f%%)', ...
                nnz(app.FinalCellMask2D), ...
                100 * nnz(app.FinalCellMask2D) / validCount); ...
                sprintf( ...
                'Uncertain/intermediate: %d (%.2f%%)', ...
                nnz(app.FinalUncertainMask2D), ...
                100 * nnz(app.FinalUncertainMask2D) / validCount); ...
                ''; ...
                'THRESHOLDS'; ...
                sprintf( ...
                'Background: Pcell <= %.2f', ...
                app.PixelBackgroundThreshold); ...
                sprintf( ...
                'Cell: Pcell >= %.2f', ...
                app.PixelCellThreshold); ...
                sprintf( ...
                'Intermediate: %.2f < Pcell < %.2f', ...
                app.PixelBackgroundThreshold, ...
                app.PixelCellThreshold)};
            
            if ~isempty(fieldnames(validation))
                
                textLines = ...
                    [ ...
                    textLines; ...
                    { ...
                    ''; ...
                    'GROUPED ROI VALIDATION'; ...
                    ['Method: ' validation.ValidationMethod]; ...
                    sprintf( ...
                    'Balanced accuracy: %.3f', ...
                    validation.BalancedAccuracy); ...
                    sprintf( ...
                    'Cell sensitivity: %.3f', ...
                    validation.CellSensitivity); ...
                    sprintf( ...
                    'Background specificity: %.3f', ...
                    validation.BackgroundSpecificity)}];
            end
            
            textLines = ...
                [ ...
                textLines; ...
                { ...
                ''; ...
                'IMPORTANT'; ...
                'Purity values are model-estimated.'; ...
                'Only confident-background pixels should be removed.'; ...
                'Uncertain pixels remain preserved for review.'}];
            
            app.TextArea.Value = ...
                textLines;
            
        end


        function displayBinaryPixelMask( ...
                app, ...
                mask2D, ...
                mapTitle, ...
                overlayColor)
            
            if isempty(mask2D)
                
                uialert( ...
                    app.UIFigure, ...
                    'Run pixel assessment first.', ...
                    'Pixel result unavailable');
                
                return
            end
            
            app.resetImageAxis(app.UIAxes_2);
            
            imagesc( ...
                app.UIAxes_2, ...
                app.PreviewMap);
            
            axis(app.UIAxes_2, 'image');
            
            app.UIAxes_2.YDir = ...
                'normal';
            
            colormap(app.UIAxes_2, gray(256));
            
            traceValues = ...
                app.PreviewMap( ...
                app.ValidMask2D & ...
                isfinite(app.PreviewMap));
            
            if ~isempty(traceValues)
                
                lowValue = ...
                    prctile(traceValues, 2);
                
                highValue = ...
                    prctile(traceValues, 98);
                
                if isfinite(lowValue) && ...
                        isfinite(highValue) && ...
                        lowValue < highValue
                    
                    clim( ...
                        app.UIAxes_2, ...
                        [lowValue highValue]);
                end
            end
            
            hold(app.UIAxes_2, 'on');
            
            rgbOverlay = ...
                zeros( ...
                [size(mask2D), 3]);
            
            for channel = 1:3
                
                colorPlane = ...
                    rgbOverlay(:, :, channel);
                
                colorPlane(mask2D) = ...
                    overlayColor(channel);
                
                rgbOverlay(:, :, channel) = ...
                    colorPlane;
            end
            
            overlayHandle = ...
                image( ...
                app.UIAxes_2, ...
                rgbOverlay);
            
            overlayHandle.AlphaData = ...
                0.58 .* double(mask2D);
            
            contour( ...
                app.UIAxes_2, ...
                mask2D, ...
                [0.5 0.5], ...
                'w', ...
                'LineWidth', ...
                1.2);
            
            hold(app.UIAxes_2, 'off');
            
            title( ...
                app.UIAxes_2, ...
                { ...
                mapTitle, ...
                sprintf('Pixels: %d', nnz(mask2D))}, ...
                'Interpreter', ...
                'none');
            
        end


        function openRepresentativeDatasetInSingleMode( ...
                app, ...
                handoffData)
            
            % =============================================================
            % 1. VALIDATE HANDOFF DATA
            % =============================================================
            
            if isempty(handoffData) || ...
                    ~isstruct(handoffData)
                
                error( ...
                    'Representative handoff data are invalid.');
            end
            
            if isempty(handoffData.Wavenumber) || ...
                    isempty(handoffData.Spectra)
                
                error( ...
                    'Representative wavenumber or spectra are empty.');
            end
            
            if size(handoffData.Spectra, 1) ~= ...
                    numel(handoffData.Wavenumber)
                
                error( ...
                    ['Representative spectra rows do not match the ' ...
                    'wavenumber axis.']);
            end
            
            if size(handoffData.Spectra, 2) ~= ...
                    numel(handoffData.Names)
                
                error( ...
                    ['Representative spectrum names do not match the ' ...
                    'number of spectra.']);
            end
            
            % =============================================================
            % 2. CLOSE PREVIOUS CHILD INSTANCE
            %
            % This does not close or alter single_extract.
            % =============================================================
            
            if ~isempty(app.SingleModeApp)
                
                try
                    
                    if isvalid(app.SingleModeApp)
                        
                        previousChoice = ...
                            uiconfirm( ...
                            app.UIFigure, ...
                            { ...
                            'A single_mode window opened by this segmentation app'; ...
                            'is already active.'; ...
                            ''; ...
                            'Replace it with the new representative dataset?'}, ...
                            'single_mode already open', ...
                            'Options', ...
                            {'Replace', 'Cancel'}, ...
                            'DefaultOption', ...
                            'Replace', ...
                            'CancelOption', ...
                            'Cancel');
                        
                        if ~strcmp(previousChoice, 'Replace')
                            
                            error( ...
                                'single_extract:SingleModeHandoffCancelled', ...
                                'single_mode handoff was cancelled.');
                            
                        end
                        
                        delete(app.SingleModeApp);
                    end
                    
                catch ME
                    
                    if strcmp( ...
                            ME.identifier, ...
                            'single_extract:SingleModeHandoffCancelled')
                        
                        rethrow(ME);
                    end
                end
            end
            
            % =============================================================
            % 3. CREATE SINGLE MODE
            % =============================================================
            
            try
                
                childApp = ...
                    single_mode();
                
            catch ME
                
                error( ...
                    ['Could not open single_mode.' newline newline ...
                    ME.message]);
            end
            
            app.SingleModeApp = ...
                childApp;
            
            % Keep the child hidden until data are completely loaded.
            try
                childApp.UIFigure.Visible = 'off';
            catch
            end
            
            % =============================================================
            % 4. BUILD METADATA
            % =============================================================
            
            reviewMeta = ...
                app.buildSingleModeReviewMetadata( ...
                handoffData);
            
            spectralDef = ...
                app.getSpectralDefinitionForSingleMode();
            
            peakLibrary = ...
                table();
            
            % =============================================================
            % 5. SEND DATA USING SINGLE_MODE'S EXISTING PUBLIC RECEIVER
            %
            % No change is required inside single_mode.
            % =============================================================
            
            try
                
                childApp.receiveReviewDefinedData( ...
                    handoffData.Wavenumber, ...
                    handoffData.Spectra, ...
                    handoffData.Names, ...
                    reviewMeta, ...
                    spectralDef, ...
                    peakLibrary);
                
            catch ME
                
                try
                    delete(childApp);
                catch
                end
                
                app.SingleModeApp = [];
                
                error( ...
                    ['single_mode rejected the representative dataset.' ...
                    newline newline ...
                    ME.message]);
            end
            
            % =============================================================
            % 6. OPEN DIRECTLY AT PREPROCESS
            %
            % receiveReviewDefinedData also synchronizes the same data to
            % Analysis internally.
            % =============================================================
            
            try
                
                childApp.TabGroup.SelectedTab = ...
                    childApp.PreprocessTab;
                
            catch ME
                
                warning( ...
                    'RamanCube:PreprocessTabSelection', ...
                    ['Representative data were loaded, but the Preprocess tab ' ...
                    'could not be selected automatically: %s'], ...
                    ME.message);
            end
            
            try
                
                childApp.UIFigure.Name = ...
                    sprintf( ...
                    'Single mode — %s — cluster representatives', ...
                    app.CurrentScanName);
                
                childApp.UIFigure.Visible = ...
                    'on';
                
                figure(childApp.UIFigure);
                
            catch
            end
            
        end
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