classdef single_mode < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        GridLayout23                    matlab.ui.container.GridLayout
        FinishButton_3                  matlab.ui.control.Button
        OneclickpipelineButton          matlab.ui.control.Button
        UserTextArea                    matlab.ui.control.TextArea
        UserTextAreaLabel               matlab.ui.control.Label
        Panel_5                         matlab.ui.container.Panel
        GridLayout24                    matlab.ui.container.GridLayout
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        SinglespectrapreprocessinganalysisLabel  matlab.ui.control.Label
        TextArea_6                      matlab.ui.control.TextArea
        ReviewdataTab                   matlab.ui.container.Tab
        GridLayout19                    matlab.ui.container.GridLayout
        AccessdataButton_2              matlab.ui.control.Button
        ControlPanel                    matlab.ui.container.Panel
        GridLayout22                    matlab.ui.container.GridLayout
        SummaryButton                   matlab.ui.control.Button
        TextArea_5                      matlab.ui.control.TextArea
        TextArea_4                      matlab.ui.control.TextArea
        TextArea_3                      matlab.ui.control.TextArea
        DetailsButton                   matlab.ui.control.Button
        CorrectQCdataButton             matlab.ui.control.Button
        TextArea_2                      matlab.ui.control.TextArea
        Tree3                           matlab.ui.container.Tree
        Node_5                          matlab.ui.container.TreeNode
        Node2_5                         matlab.ui.container.TreeNode
        Node3_5                         matlab.ui.container.TreeNode
        Node4_5                         matlab.ui.container.TreeNode
        UIAxes8                         matlab.ui.control.UIAxes
        PreprocessTab                   matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        GoAnalysisButton                matlab.ui.control.Button
        GridLayout4                     matlab.ui.container.GridLayout
        CancelButton                    matlab.ui.control.Button
        FinishButton                    matlab.ui.control.Button
        PreprocessdataPanel             matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        SaveimagesButton                matlab.ui.control.Button
        SmoothButton                    matlab.ui.control.Button
        BaselinecorrectButton           matlab.ui.control.Button
        CosmicremoveButton              matlab.ui.control.Button
        DatadefineButton                matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        UIAxes                          matlab.ui.control.UIAxes
        AnalysisTab                     matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        MeanspectraTab                  matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        TextArea_7                      matlab.ui.control.TextArea
        Panel_3                         matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        SaveimagesButton_2              matlab.ui.control.Button
        Panel_4                         matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        RatiocalculationButton          matlab.ui.control.Button
        Panel_2                         matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        ListBox                         matlab.ui.control.ListBox
        BandcheckButton                 matlab.ui.control.Button
        StandardDeviationSwitch         matlab.ui.control.Switch
        StandardDeviationSwitchLabel    matlab.ui.control.Label
        OffsetSwitch                    matlab.ui.control.Switch
        OffsetLabel                     matlab.ui.control.Label
        GridLayout10                    matlab.ui.container.GridLayout
        ResetscaleButton                matlab.ui.control.Button
        SpectraldisplayButton           matlab.ui.control.Button
        WavenumberfilterEditField       matlab.ui.control.EditField
        WavenumberfilterEditFieldLabel  matlab.ui.control.Label
        GroupdataDropDown               matlab.ui.control.DropDown
        GroupdataDropDownLabel          matlab.ui.control.Label
        SpectraltypesDropDown           matlab.ui.control.DropDown
        SpectraltypesDropDownLabel      matlab.ui.control.Label
        DatacheckPanel                  matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        LabelgroupsButton               matlab.ui.control.Button
        ReviewdatastructureButton       matlab.ui.control.Button
        GridLayout6                     matlab.ui.container.GridLayout
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes7                         matlab.ui.control.UIAxes
        RatioTab                        matlab.ui.container.Tab
        GridLayout30                    matlab.ui.container.GridLayout
        Tree6                           matlab.ui.container.Tree
        Node_8                          matlab.ui.container.TreeNode
        Node2_8                         matlab.ui.container.TreeNode
        Node3_8                         matlab.ui.container.TreeNode
        Node4_8                         matlab.ui.container.TreeNode
        RatiosettingsPanel              matlab.ui.container.Panel
        GridLayout31                    matlab.ui.container.GridLayout
        AnalyzemodeButton               matlab.ui.control.Button
        TestDropDown                    matlab.ui.control.DropDown
        TestDropDownLabel               matlab.ui.control.Label
        FinishButton_4                  matlab.ui.control.Button
        InterpretmodeButton             matlab.ui.control.Button
        ExcelsupportButton              matlab.ui.control.Button
        yaxisfilterButton               matlab.ui.control.Button
        YaxisfilterEditField            matlab.ui.control.EditField
        ResetButton                     matlab.ui.control.Button
        ApplyratioButton                matlab.ui.control.Button
        FeaturesDropDown                matlab.ui.control.DropDown
        FeaturesDropDownLabel           matlab.ui.control.Label
        FormulaButton                   matlab.ui.control.Button
        AddpeaksButton                  matlab.ui.control.Button
        UITable2                        matlab.ui.control.Table
        Tree5                           matlab.ui.container.Tree
        Node_7                          matlab.ui.container.TreeNode
        Node2_7                         matlab.ui.container.TreeNode
        Node3_7                         matlab.ui.container.TreeNode
        Node4_7                         matlab.ui.container.TreeNode
        UIAxes10                        matlab.ui.control.UIAxes
        PCATab                          matlab.ui.container.Tab
        GridLayout12                    matlab.ui.container.GridLayout
        LoadingspeaksButton             matlab.ui.control.Button
        SaveimagesButton_3              matlab.ui.control.Button
        GridLayout15                    matlab.ui.container.GridLayout
        GridLayout17                    matlab.ui.container.GridLayout
        FilterButton_2                  matlab.ui.control.Button
        EditField_3                     matlab.ui.control.EditField
        peakEditField                   matlab.ui.control.EditField
        peakEditFieldLabel              matlab.ui.control.Label
        HighpeaksCheckBox               matlab.ui.control.CheckBox
        UIAxes6_2                       matlab.ui.control.UIAxes
        GridLayout14                    matlab.ui.container.GridLayout
        GridLayout16                    matlab.ui.container.GridLayout
        LocateclustersCheckBox          matlab.ui.control.CheckBox
        ScoresizeSlider                 matlab.ui.control.Slider
        ScoresizeSliderLabel            matlab.ui.control.Label
        UIAxes6                         matlab.ui.control.UIAxes
        GridLayout13                    matlab.ui.container.GridLayout
        FilterButton                    matlab.ui.control.Button
        EditField_2                     matlab.ui.control.EditField
        ConfirmdatastructureforPCAButton  matlab.ui.control.Button
        Tree2                           matlab.ui.container.Tree
        Node_2                          matlab.ui.container.TreeNode
        Node2_2                         matlab.ui.container.TreeNode
        Node3_2                         matlab.ui.container.TreeNode
        Node4_2                         matlab.ui.container.TreeNode
        Tree                            matlab.ui.container.Tree
        Node                            matlab.ui.container.TreeNode
        Node2                           matlab.ui.container.TreeNode
        Node3                           matlab.ui.container.TreeNode
        Node4                           matlab.ui.container.TreeNode
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        ClassificationTab               matlab.ui.container.Tab
        GridLayout12_2                  matlab.ui.container.GridLayout
        SaveimagesButton_4              matlab.ui.control.Button
        GridLayout15_2                  matlab.ui.container.GridLayout
        GridLayout17_2                  matlab.ui.container.GridLayout
        FilterButton_4                  matlab.ui.control.Button
        EditField_5                     matlab.ui.control.EditField
        peakEditField_2                 matlab.ui.control.EditField
        peakEditField_2Label            matlab.ui.control.Label
        HighpeaksCheckBox_2             matlab.ui.control.CheckBox
        UIAxes6_4                       matlab.ui.control.UIAxes
        GridLayout14_2                  matlab.ui.container.GridLayout
        GridLayout16_2                  matlab.ui.container.GridLayout
        LocateclustersCheckBox_2        matlab.ui.control.CheckBox
        ScoresizeSlider_2               matlab.ui.control.Slider
        ScoresizeSlider_2Label          matlab.ui.control.Label
        UIAxes6_3                       matlab.ui.control.UIAxes
        GridLayout13_2                  matlab.ui.container.GridLayout
        ClassificationButton            matlab.ui.control.Button
        MethodsDropDown                 matlab.ui.control.DropDown
        MethodsDropDownLabel            matlab.ui.control.Label
        Tree2_2                         matlab.ui.container.Tree
        Node_4                          matlab.ui.container.TreeNode
        Node2_4                         matlab.ui.container.TreeNode
        Node3_4                         matlab.ui.container.TreeNode
        Node4_4                         matlab.ui.container.TreeNode
        Tree_2                          matlab.ui.container.Tree
        Node_3                          matlab.ui.container.TreeNode
        Node2_3                         matlab.ui.container.TreeNode
        Node3_3                         matlab.ui.container.TreeNode
        Node4_3                         matlab.ui.container.TreeNode
        UIAxes4_2                       matlab.ui.control.UIAxes
        UIAxes5_2                       matlab.ui.control.UIAxes
        SummarizeresultsTab             matlab.ui.container.Tab
        GridLayout32                    matlab.ui.container.GridLayout
        Tree7                           matlab.ui.container.Tree
        Node_9                          matlab.ui.container.TreeNode
        Node2_9                         matlab.ui.container.TreeNode
        Node3_9                         matlab.ui.container.TreeNode
        Node4_9                         matlab.ui.container.TreeNode
        DropDown                        matlab.ui.control.DropDown
        UpdateButton                    matlab.ui.control.Button
        OpendetailsButton               matlab.ui.control.Button
        TextArea_8                      matlab.ui.control.TextArea
        ComparativesubjectDropDown      matlab.ui.control.DropDown
        comparativesubjectLabel         matlab.ui.control.Label
        UIAxes11                        matlab.ui.control.UIAxes
        SpatialdistributionTab          matlab.ui.container.Tab
        GridLayout28                    matlab.ui.container.GridLayout
        ControlsettingsPanel            matlab.ui.container.Panel
        GridLayout29                    matlab.ui.container.GridLayout
        OptimizescanButton              matlab.ui.control.Button
        ColorscaleDropDown              matlab.ui.control.DropDown
        ColorscaleDropDownLabel         matlab.ui.control.Label
        PlotmapButton                   matlab.ui.control.Button
        ColormapDropDown                matlab.ui.control.DropDown
        ColormapDropDownLabel           matlab.ui.control.Label
        MapsourceDropDown               matlab.ui.control.DropDown
        MapsourceDropDownLabel          matlab.ui.control.Label
        DisplaymaptypeDropDown          matlab.ui.control.DropDown
        DisplaymaptypeDropDownLabel     matlab.ui.control.Label
        DatasetDropDown_6               matlab.ui.control.DropDown
        DatasetDropDown_6Label          matlab.ui.control.Label
        DatasetDropDown_5               matlab.ui.control.DropDown
        DatasetDropDown_5Label          matlab.ui.control.Label
        DatasetDropDown_4               matlab.ui.control.DropDown
        DatasetDropDown_4Label          matlab.ui.control.Label
        DatasetDropDown_3               matlab.ui.control.DropDown
        DatasetDropDown_3Label          matlab.ui.control.Label
        DatasetDropDown_2               matlab.ui.control.DropDown
        DatasetDropDown_2Label          matlab.ui.control.Label
        DatasetDropDown                 matlab.ui.control.DropDown
        DatasetLabel                    matlab.ui.control.Label
        UIAxes9                         matlab.ui.control.UIAxes
        UIAxes9_2                       matlab.ui.control.UIAxes
        UIAxes9_3                       matlab.ui.control.UIAxes
        UIAxes9_4                       matlab.ui.control.UIAxes
        UIAxes9_5                       matlab.ui.control.UIAxes
        UIAxes9_6                       matlab.ui.control.UIAxes
        ReportTab                       matlab.ui.container.Tab
        GridLayout25                    matlab.ui.container.GridLayout
        AcitivitiesPanel                matlab.ui.container.Panel
        GridLayout27                    matlab.ui.container.GridLayout
        ExportPDFButton                 matlab.ui.control.Button
        PreviewButton                   matlab.ui.control.Button
        ResettemplateButton             matlab.ui.control.Button
        MovedownButton                  matlab.ui.control.Button
        MoveupButton                    matlab.ui.control.Button
        RemoveButton                    matlab.ui.control.Button
        AddButton                       matlab.ui.control.Button
        SettingsPanel                   matlab.ui.container.Panel
        GridLayout26                    matlab.ui.container.GridLayout
        ReportDescriptionLabel          matlab.ui.control.Label
        CheckBox                        matlab.ui.control.CheckBox
        ReportLayoutDropDown            matlab.ui.control.DropDown
        ReportLayoutLabel               matlab.ui.control.Label
        ReportRowsPageEditField         matlab.ui.control.EditField
        ReportRowsPageEditFieldLabel    matlab.ui.control.Label
        ReportTopEditField              matlab.ui.control.EditField
        ReportTopEditFieldLabel         matlab.ui.control.Label
        ReportCustomTitleEditField      matlab.ui.control.EditField
        ReportCustomTitleEditFieldLabel  matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        Tree4                           matlab.ui.container.Tree
        Node_6                          matlab.ui.container.TreeNode
        Node2_6                         matlab.ui.container.TreeNode
        Node3_6                         matlab.ui.container.TreeNode
        Node4_6                         matlab.ui.container.TreeNode
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end

    
    properties (Access = public)
        mainHomeApp
        singleAnalysisApp
        
        mode
        wn
        
        % ===== Single spectra Home / QC session =====
        homeSession struct = struct()
        homeUser string = ""
        homeRole string = ""
        homeEmail string = ""
        
        singleInputFolder string = "E:\PROJECTS\RamanCube\Data input\single"
        singleOutputRoot string = "E:\PROJECTS\RamanCube\Data output\single"
        singleOutputSessionFolder string = ""
        
        singleHomeLoaded logical = false
        singleHomeCorrected logical = false
        
        singleData struct = struct()
        singleQC struct = struct()
        singleGroupTable table = table()
        singleFileTable table = table()
        
        singleLastSelectedGroups string = strings(0,1)
        singleLastSessionFile string = ""
        singleWNAccepted logical = false
        singleWNAcceptNote string = ""
        singleWNAcceptedAt string = ""
        singleStrictLabelPattern string = ...
            "^[A-Za-z0-9]+(?:_[A-Za-z0-9]+)+$"
        singleSessionName string = ""
        singleSessionID string = ""
        
        spectra
        cube
        
        rawSpectra
        rawCube
        
        projectName
        projectFolder
        manageDataFolder
        
        shouldSaveData logical = false
        processHistory string = ""
        
        cosmicRemoved logical = false
        lastBandMode string = ""
        lastBandWn double = NaN
        lastBandLow double = NaN
        lastBandHigh double = NaN
        
        spectralDef
        % ===== Data review & define state =====
        dataReviewState struct = struct()
        
        % ===== Mean spectra analysis data =====
        analysisSource string = ""
        analysisWn double = []
        analysisSpectra double = []
        analysisRawSpectra double = []
        analysisNames string = strings(0,1)
        analysisHistory string = ""
        
        baseGroups struct = struct('Name', {}, 'Indices', {}, 'Count', {})
        compareGroups struct = struct('Name', {}, 'BaseGroupNames', {}, 'Indices', {}, 'Count', {})
        
        currentSpectralType string = ""
        currentNormMethod string = ""
        currentGroupSelection string = ""
        currentSelectedGroupIdx double = []
        currentWnMask logical = []
        currentXLim double = []
        currentYLim double = []
        
        plottedMeanSpectra double = []
        plottedSDSpectra double = []
        plottedGroupNames string = strings(0,1)
        plottedWn double = []
        
        ratioFormula string = ""
        ratioBands table = table()
        ratioResults table = table()
        
        peakFeatureType string = ""
        boxplotResults table = table()
        
        names string = strings(0,1)
        rawNames string = strings(0,1)
        
        % ===== PCA analysis =====
        pcaX double = []
        pcaWn double = []
        pcaScores double = []
        pcaLoadings double = []
        pcaExplained double = []
        pcaGroupLabels string = strings(0,1)
        pcaGroupNames string = strings(0,1)
        pcaSpectrumNames string = strings(0,1)
        pcaNormMethod string = ""
        pcaSourceX double = []
        pcaReferenceValues double = []
        pcaReferenceScaledX double = []
        pcaReferenceDefinition struct = struct()
        pcaReferenceFeatureType string = ""


        currentPCX double = 1
        currentPCY double = 2
        currentLoadingPCs double = []
        
        
        peakLibrary table = table()
        pcaInputType string = "Spectra"
        pcaVariableNames string = strings(0,1)
        ratioScreeningSelectedPeaks table = table()
        ratioScreeningAll table = table()
        ratioScreeningTop table = table()
        ratioScreeningValues double = []
        ratioScreeningGroupLabels string = strings(0,1)
        ratioScreeningGroupNames string = strings(0,1)
        ratioScreeningSpectrumNames string = strings(0,1)
        
        % ===== Classification analysis =====
        classMethod string = ""
        classX double = []
        classWn double = []
        classGroupLabels string = strings(0,1)
        classGroupNames string = strings(0,1)
        classSpectrumNames string = strings(0,1)
        classNormMethod string = ""
        
        classScores double = []
        classLoadings double = []
        classExplained double = []
        classComponentNames string = strings(0,1)
        
        classPredictedLabels string = strings(0,1)
        classAccuracy double = NaN
        classConfusion table = table()
        
        currentClassScoreX double = 1
        currentClassScoreY double = 2
        currentClassLoadings double = []
        
        % ===== Scan analysis =====
        scanLoaded logical = false
        scanWn double = []
        scanRawCube double = []
        scanProcessedCube double = []
        scanBandLow double = 2840
        scanBandHigh double = 2940
        scanCurrentAxis1Map double = []
        scanCurrentAxis2Map double = []
        scanCurrentAxis1Title string = ""
        scanCurrentAxis2Title string = ""
        scanCurrentFeatureMap double = []
        scanCurrentFeatureName string = ""
        scanCurrentFeatureType string = ""
        scanCurrentFeatureFormula string = ""
        singleSNRThreshold double = 10
        singleSNRAccepted logical = false
        singleSNRAcceptNote string = ""
        singleWarningViewMode string = ""
        scanFeatures struct = struct( ...
            'Name', {}, ...
            'Type', {}, ...
            'Map', {}, ...
            'Formula', {}, ...
            'BandInfo', {}, ...
            'Source', {}, ...
            'CreatedAt', {})
        scanBandRanges double = [2840 2940]
        scanBandCombineMode string = "Average"
        scanBandDisplayText string = "2840 2940"
        scanMapColor string = "turbo"
        scanOptimizeMode string = "None"
        scanOptimizePercentile double = [1 99]
        scanManualCLim double = [NaN NaN]
        
        % ===== Single-mode workflow / audit / preview =====
        ActivityHistory table = table()
        MetricHistory table = table()
        ConfirmedStages table = table()
        PendingPreview struct = struct()
        
        CurrentStage string = "No data"
        CurrentDataStatus string = "Empty"
        
        OneClickResult struct = struct()
        OneClickMode string = "Exploratory"
        
        SaveIntermediateData logical = false
        isReturningToHome logical = false
        % ===== Flexible preprocess checkpoint workflow =====
        preprocessCheckpoints struct = struct()
        pendingPreprocessContext struct = struct()
        reviewMeta table = table()
        
        % ===== Report builder =====
        ReportSpec table = table()
        ReportAvailableSections table = table()
        SelectedReportRow double = []
        ReportBuilderInitialized logical = false


        % =============================================================
        % SPATIAL DISTRIBUTION WORKSPACE
        %
        % IMPORTANT DESIGN RULES
        %
        % 1. Spatial panels NEVER own/copy Raman cubes.
        % 2. Datasets are referenced by metadata/path only.
        % 3. Map matrices are temporary RAM cache only.
        % 4. Session/project save stores recipes + provenance, not maps.
        % 5. A map can always be reconstructed from:
        %
        %       source dataset + source stage + map recipe
        %
        % =============================================================
        
        spatialInitialized logical = false
        
        % -------------------------------------------------------------
        % DATASET REGISTRY
        %
        % One row/entry = one spatially resolved Raman scan.
        %
        % Cube itself is NOT stored here.
        % -------------------------------------------------------------
        spatialDatasets struct = struct( ...
            'ID', {}, ...
            'DisplayName', {}, ...
            'GroupName', {}, ...
            'SourceScanName', {}, ...
            'RawCubePath', {}, ...
            'ProcessedCubePath', {}, ...
            'WavenumberPath', {}, ...
            'MetadataPath', {}, ...
            'SourceSessionFolder', {}, ...
            'SourceStage', {}, ...
            'Ny', {}, ...
            'Nx', {}, ...
            'NWn', {}, ...
            'WnMin', {}, ...
            'WnMax', {}, ...
            'CreatedAt', {})
        
        % -------------------------------------------------------------
        % SIX INDEPENDENT VIEWPORTS
        %
        % MapData and ReferenceData are RAM-only caches.
        % They will NOT be stored in permanent metadata backup.
        % -------------------------------------------------------------
        spatialPanels struct = struct( ...
            'PanelID', {}, ...
            'DatasetID', {}, ...
            'DatasetName', {}, ...
            'Recipe', {}, ...
            'MapData', {}, ...
            'ReferenceData', {}, ...
            'MapTitle', {}, ...
            'MapUnit', {}, ...
            'Calculated', {}, ...
            'CalculatedAt', {}, ...
            'CLim', {})
        
        % -------------------------------------------------------------
        % CURRENT MAP CONSTRUCTOR
        % -------------------------------------------------------------
        spatialCurrentRecipe struct = struct()
        
        spatialMapSource string = "Processed"
        spatialMapType string = "Integrated band"
        spatialColorMap string = "hot"
        spatialColorScale string = "Shared"
        
        % -------------------------------------------------------------
        % DEFAULT REFERENCE IMAGE
        %
        % Used immediately when a dataset is selected in one viewport.
        % -------------------------------------------------------------
        spatialReferenceBand double = [2800 2920]
        
        % -------------------------------------------------------------
        % TEMPORARY DATASET CACHE
        %
        % Only ONE dataset is cached at a time by default.
        % Prevents loading six cubes simultaneously.
        % -------------------------------------------------------------
        spatialCache struct = struct( ...
            'DatasetID', "", ...
            'RawCube', [], ...
            'ProcessedCube', [], ...
            'Wn', [], ...
            'ValidMask', [], ...
            'CellMask', [], ...
            'BackgroundMask', [], ...
            'UncertainMask', [], ...
            'AnalysisMask', [], ...
            'MaskSource', "", ...
            'LoadedAt', "")
        
        % -------------------------------------------------------------
        % MAP RECIPE HISTORY
        %
        % SMALL metadata only.
        % -------------------------------------------------------------
        spatialRecipeHistory struct = struct( ...
            'RecipeID', {}, ...
            'MapType', {}, ...
            'MapSource', {}, ...
            'Parameters', {}, ...
            'ColorMap', {}, ...
            'ColorScale', {}, ...
            'CreatedAt', {}, ...
            'User', {})
        
        % -------------------------------------------------------------
        % AUDIT / METADATA PATH
        % -------------------------------------------------------------
        spatialMetadataVersion string = "spatial_distribution_v1"
        % =============================================================
        % INTEGRATED INTERPRETATION / SUMMARY REGISTRY
        %
        % DESIGN RULES
        %
        % 1. Registry stores SMALL metadata only.
        % 2. No Raman spectra are duplicated here.
        % 3. No ratio-value matrix is duplicated here.
        % 4. No PCA score/loading matrix is duplicated here.
        % 5. One EvidenceID identifies one interpreted comparison/result.
        % 6. Re-interpreting the same evidence UPDATES the existing row.
        % 7. Summarize results reads this registry only.
        % =============================================================


    end
    
    properties (Access = private)
        % =============================================================
        % ANALYSIS RECIPE / METADATA REGISTRY
        %
        % IMPORTANT:
        % These properties store definitions / recipes only.
        % Do NOT store duplicated Raman matrices here.
        % =============================================================
        
        analysisMetadataVersion string = ...
            "ramancube_analysis_metadata_v3"
        
        % Saved ratio recipes.
        % One row = one reusable ratio definition.
        ratioDefinitions table = table()
        
        
        % =============================================================
        % RATIO ANALYZE WORKSPACE
        %
        % RAM / interactive state.
        %
        % Heavy matrices are NOT stored in Analysis Metadata.
        % Session save already preserves final screening matrices through
        % ratioScreeningAll / ratioScreeningValues / etc.
        % =============================================================
        
        % Canonical peak table used by Ratio.
        % Does NOT replace the rich app.peakLibrary.
        ratioPeakTable table = table()
        
        % Peak-feature values before division.
        % rows    = spectra
        % columns = currently selected peaks
        ratioFeatureMatrix double = []
        
        % Current Ratio-group labels aligned with spectra.
        ratioAnalysisGroupLabels string = strings(0,1)
        ratioAnalysisGroupNames string = strings(0,1)
        
        % Current table-to-result mapping.
        % Needed because Tree6 can filter the visible UITable.
        ratioVisibleRowMap double = []
        
        % Selected row in complete ratioScreeningAll.
        ratioSelectedResultIndex double = NaN
        
        % Display-only manual Y limits.
        ratioBoxYLim double = []
        
        % Current feature/test recipe.
        ratioFeatureType string = "Area"
        ratioTestType string = "Auto"
        
        % Formula run information.
        ratioFormulaVariables string = strings(0,1)
        ratioFormulaPeakIDs double = []
        
        % True when the Ratio workspace corresponds to current analysis data.
        ratioAnalyzeReady logical = false
        
        % Last PCA run recipe.
        % No PCA matrix is permanently duplicated here.
        pcaRunRecipe struct = struct()
        
        % PCA center used for projection to spatial maps.
        % RAM state only; final metadata stores recipe, not this vector.
        pcaCenter double = []
        
        % Last classification run recipe.
        classRunRecipe struct = struct()
        
        % Classification model is RAM-only.
        % It is deliberately NOT included in metadata backup.
        classModel = []
        classModelType string = ""
        
        
        interpretationMetadataVersion string = ...
            "ramancube_interpretation_v1"
        interpretationRegistry table = table()
        % Temporary interpretation session.
        % RAM ONLY — never saved as a finished interpretation.
        interpretSession struct = struct()
        % Ratio tab mode.
        ratioViewMode string = "Analyze"
        % Currently selected Ratio result row.
        ratioSelectedRow double = NaN
        % Currently selected evidence in Summarize results.
        summarySelectedEvidenceID string = ""
        % Summary becomes dirty whenever interpreted evidence changes.
        summaryDirty logical = true
        
        % =============================================================
        % SPATIAL OPTIMIZED OVERLAY HISTORY
        %
        % Metadata ONLY.
        % No map, mask, or Raman cube is stored here.
        % =============================================================
        
        spatialOptimizeHistory struct = struct( ...
            'OverlayID', {}, ...
            'PanelID', {}, ...
            'DatasetID', {}, ...
            'DatasetName', {}, ...
            'MapType', {}, ...
            'SelectionMode', {}, ...
            'PercentileLow', {}, ...
            'PercentileHigh', {}, ...
            'ValueThresholdLow', {}, ...
            'ValueThresholdHigh', {}, ...
            'Opacity', {}, ...
            'CreatedAt', {}, ...
            'User', {})
    end


    %% Public functions
    methods (Access = public)
        % Main home


        function receiveHomeSession( ...
                app, ...
                sessionInfo, ...
                homeApp, ...
                resumeFile)
            
            % =========================================================
            % RECEIVEHOMESESSION
            %
            % Receives the active Home session and initializes Single mode.
            %
            % Inputs:
            %   sessionInfo : session structure created by Home
            %   homeApp     : Home app object
            %   resumeFile  : optional previous single_home_session.mat
            % =========================================================
            
            % =========================================================
            % 1. OPTIONAL RESUME FILE
            % =========================================================
            if nargin < 4 || isempty(resumeFile)
                resumeFile = "";
            end
            
            resumeFile = string(resumeFile);
            
            % =========================================================
            % 2. VALIDATE SESSION INFORMATION
            % =========================================================
            if nargin < 2 || isempty(sessionInfo) || ~isstruct(sessionInfo)
                
                error( ...
                    'single_mode:InvalidHomeSession', ...
                    'The session information received from Home is invalid.');
                
            end
            
            % =========================================================
            % 3. VALIDATE HOME APP
            % =========================================================
            if nargin < 3 || isempty(homeApp)
                
                error( ...
                    'single_mode:MissingHomeApp', ...
                    'The Home application reference was not provided.');
                
            end
            
            try
                homeIsValid = isvalid(homeApp);
            catch
                homeIsValid = false;
            end
            
            if ~homeIsValid
                
                error( ...
                    'single_mode:InvalidHomeApp', ...
                    'The Home application reference is no longer valid.');
                
            end
            
            % =========================================================
            % 4. STORE HOME APP REFERENCE
            % =========================================================
            app.mainHomeApp = homeApp;
            
            % =========================================================
            % 5. STORE COMPLETE HOME SESSION
            % =========================================================
            app.homeSession = sessionInfo;
            
            % =========================================================
            % 6. USER IDENTITY
            % =========================================================
            if isfield(sessionInfo, 'User')
                app.homeUser = string(sessionInfo.User);
            else
                app.homeUser = "";
            end
            
            if isfield(sessionInfo, 'Role')
                app.homeRole = string(sessionInfo.Role);
            else
                app.homeRole = "";
            end
            
            if isfield(sessionInfo, 'Email')
                app.homeEmail = string(sessionInfo.Email);
            else
                app.homeEmail = "";
            end
            
            % =========================================================
            % 7. SESSION ID
            % =========================================================
            if isfield(sessionInfo, 'SessionID')
                app.singleSessionID = string(sessionInfo.SessionID);
            else
                app.singleSessionID = "";
            end
            
            % =========================================================
            % 8. INPUT FOLDER
            % =========================================================
            if isfield(sessionInfo, 'SelectedFolder') && ...
                    strlength(string(sessionInfo.SelectedFolder)) > 0
                
                app.singleInputFolder = ...
                    string(sessionInfo.SelectedFolder);
                
            elseif isfield(sessionInfo, 'SingleInputRoot') && ...
                    strlength(string(sessionInfo.SingleInputRoot)) > 0
                
                app.singleInputFolder = ...
                    string(sessionInfo.SingleInputRoot);
                
            end
            
            % =========================================================
            % 9. OUTPUT ROOT
            % =========================================================
            if isfield(sessionInfo, 'SingleOutputRoot') && ...
                    strlength(string(sessionInfo.SingleOutputRoot)) > 0
                
                app.singleOutputRoot = ...
                    string(sessionInfo.SingleOutputRoot);
                
            elseif isfield(sessionInfo, 'OutputRoot') && ...
                    strlength(string(sessionInfo.OutputRoot)) > 0
                
                app.singleOutputRoot = string( ...
                    fullfile( ...
                    string(sessionInfo.OutputRoot), ...
                    'single'));
                
            end
            
            % =========================================================
            % 10. PROJECT INFORMATION
            % =========================================================
            if isfield(sessionInfo, 'ProjectRoot')
                app.projectFolder = string(sessionInfo.ProjectRoot);
            end
            
            if isfield(sessionInfo, 'DataType')
                app.projectName = string(sessionInfo.DataType);
            end
            
            % =========================================================
            % 11. BASIC MODE INITIALIZATION
            % =========================================================
            app.mode = 1;
            
            % =========================================================
            % 12. PREPARE SINGLE MODE USER INTERFACE
            % =========================================================
            app.prepareSingleHomeUI();
            app.populateSingleHomeTree();
            
            app.TabGroup.SelectedTab = app.HomeTab;


            % =========================================================
            % 13. DISPLAY CURRENT USER
            % =========================================================
            try
                app.UserTextArea.Editable = 'off';
                
                if strlength(app.homeUser) > 0
                    app.UserTextArea.Value = {char(app.homeUser)};
                else
                    app.UserTextArea.Value = {'Unknown user'};
                end
            catch
            end
            
            % =========================================================
            % 14. RESUME PREVIOUS SESSION WHEN REQUESTED
            % =========================================================
            if strlength(resumeFile) > 0
                
                if ~isfile(resumeFile)
                    
                    error( ...
                        'single_mode:ResumeFileNotFound', ...
                        'The selected session file does not exist:\n%s', ...
                        resumeFile);
                    
                end
                
                restoreSuccessful = ...
                    app.loadSingleSessionFromFile( ...
                    resumeFile, ...
                    false);
                
                if ~restoreSuccessful
                    
                    error( ...
                        'single_mode:ResumeFailed', ...
                        'The previous Single spectra session could not be restored.');
                    
                end
                
                app.singleLastSessionFile = resumeFile;
                
            else
                
                % =====================================================
                % 15. INITIALIZE NEW SESSION
                % =====================================================
                app.singleHomeLoaded = false;
                app.singleHomeCorrected = false;
                
                app.singleData = struct();
                app.singleQC = struct();
                
                app.singleGroupTable = table();
                app.singleFileTable = table();
                
                app.singleLastSelectedGroups = strings(0,1);
                app.singleLastSessionFile = "";
                
                app.singleWNAccepted = false;
                app.singleWNAcceptNote = "";
                app.singleWNAcceptedAt = "";
                
                app.spectra = [];
                app.cube = [];
                
                app.rawSpectra = [];
                app.rawCube = [];
                
                app.wn = [];
                app.names = strings(0,1);
                
                app.processHistory = "";
                app.cosmicRemoved = false;
                
                app.spectralDef = struct();
                app.dataReviewState = struct();
                
                app.analysisSource = "";
                app.analysisWn = [];
                app.analysisSpectra = [];
                app.analysisRawSpectra = [];
                app.analysisNames = strings(0,1);
                app.analysisHistory = "";
                
                app.shouldSaveData = false;
                
                % Generate a readable default session name.
                if strlength(app.singleSessionID) > 0
                    
                    app.singleSessionName = ...
                        "Single_" + app.singleSessionID;
                    
                else
                    
                    app.singleSessionName = ...
                        "Single_" + string(datetime( ...
                        'now', ...
                        'Format', ...
                        'yyyyMMdd_HHmmss'));
                    
                end
                
                app.singleOutputSessionFolder = "";
                
            end
            
            % =========================================================
            % 16. REFRESH UI AFTER INITIALIZATION OR RESTORE
            % =========================================================
            app.populateSingleHomeTree();
            
            
            % =========================================================
            % INITIALIZE / RESTORE SPATIAL DISTRIBUTION UI
            % =========================================================
            try
                
                if strlength(resumeFile) > 0 && ...
                        app.spatialInitialized
                    
                    app.restoreSpatialDistributionUI();
                    
                else
                    
                    app.initializeSpatialDistribution();
                    
                end
                
            catch spatialErr
                
                warning( ...
                    'RamanCube:SpatialInitializationFailed', ...
                    'Spatial distribution initialization failed: %s', ...
                    spatialErr.message);
                
            end
            
            
            % =========================================================
            % REFRESH MAIN DISPLAY
            % =========================================================
            try
                
                app.refreshMainAxesAfterChild();
                
            catch
            end
            
            
            drawnow;
            
            % =========================================================
            % 17. AUDIT LOG
            % =========================================================
            if strlength(resumeFile) > 0
                
                app.logActivity( ...
                    "Home", ...
                    "Open Single mode", ...
                    "Resume session", ...
                    "OPENED", ...
                    resumeFile);
                
            else
                
                app.logActivity( ...
                    "Home", ...
                    "Open Single mode", ...
                    "New session", ...
                    "OPENED", ...
                    "Input folder: " + app.singleInputFolder);
                
            end
            
        end
        
        function projectName = getCurrentProjectName(app)
            
            projectName = string(app.projectName);
            
        end
        
        
        function [added, messageText] = ...
                receiveRatioNote( ...
                app, ...
                ratioRow, ...
                featureType)
            
            added = false;
            messageText = "";
            
            
            if isempty(ratioRow) || ...
                    ~istable(ratioRow) || ...
                    height(ratioRow) ~= 1
                
                messageText = ...
                    "Ratio note must contain exactly one ratio.";
                
                return
            end
            
            
            vars = ...
                string(ratioRow.Properties.VariableNames);
            
            
            requiredVars = [ ...
                "RatioName", ...
                "NumPeak", ...
                "DenPeak", ...
                "NumeratorLowWN", ...
                "NumeratorHighWN", ...
                "NumeratorCenterWN", ...
                "DenominatorLowWN", ...
                "DenominatorHighWN", ...
                "DenominatorCenterWN"];
            
            
            if ~all(ismember(requiredVars, vars))
                
                messageText = ...
                    "This result does not contain enough ratio provenance to be saved.";
                
                return
            end
            
            
            % =========================================================
            % Persistent Ratio notes currently support explicit A/B
            % peak ratios.
            %
            % Complex Formula mode must store variable-to-peak mapping
            % before it can be reproducibly reconstructed.
            % =========================================================
            
            if ~isfinite(ratioRow.NumPeak) || ...
                    ~isfinite(ratioRow.DenPeak)
                
                messageText = ...
                    [ ...
                    "Formula result cannot yet be stored as a reusable " ...
                    "Ratio note because its variable-to-peak mapping is " ...
                    "not persisted." ...
                    ];
                
                return
            end
            
            
            recipeID = ...
                "RATIO_" + ...
                string(datetime( ...
                'now', ...
                'Format', ...
                'yyyyMMdd_HHmmss_SSS'));
            
            
            RatioName = ...
                string(ratioRow.RatioName);
            
            Formula = ...
                RatioName;
            
            FeatureType = ...
                string(featureType);
            
            NumPeak = ...
                double(ratioRow.NumPeak);
            
            DenPeak = ...
                double(ratioRow.DenPeak);
            
            NumLowWN = ...
                double(ratioRow.NumeratorLowWN);
            
            NumHighWN = ...
                double(ratioRow.NumeratorHighWN);
            
            NumCenterWN = ...
                double(ratioRow.NumeratorCenterWN);
            
            DenLowWN = ...
                double(ratioRow.DenominatorLowWN);
            
            DenHighWN = ...
                double(ratioRow.DenominatorHighWN);
            
            DenCenterWN = ...
                double(ratioRow.DenominatorCenterWN);
            
            Source = ...
                "Ratio note";
            
            CreatedAt = ...
                string(datetime( ...
                'now', ...
                'Format', ...
                'yyyy-MM-dd HH:mm:ss'));
            
            
            def = table( ...
                recipeID, ...
                RatioName, ...
                Formula, ...
                FeatureType, ...
                NumPeak, ...
                DenPeak, ...
                NumLowWN, ...
                NumHighWN, ...
                NumCenterWN, ...
                DenLowWN, ...
                DenHighWN, ...
                DenCenterWN, ...
                Source, ...
                CreatedAt, ...
                'VariableNames', { ...
                'RecipeID', ...
                'RatioName', ...
                'Formula', ...
                'FeatureType', ...
                'NumPeak', ...
                'DenPeak', ...
                'NumLowWN', ...
                'NumHighWN', ...
                'NumCenterWN', ...
                'DenLowWN', ...
                'DenHighWN', ...
                'DenCenterWN', ...
                'Source', ...
                'CreatedAt'});
            
            
            [added, messageText] = ...
                app.addRatioDefinitionInternal(def);
            
            
            if added
                
                app.saveAnalysisMetadata(false);
                
            end
            
        end


        function showFromChild(app, sourceName)
            
            if nargin < 2 || strlength(string(sourceName)) == 0
                sourceName = "Child app";
            end
            
            
            % =========================================================
            % Refresh current UI after child processing
            % =========================================================
            app.syncCurrentDataToAnalysis();
            
            app.refreshMainAxesAfterChild();
            
            app.refreshScanAnalysisAfterPreprocess();
            
            
            % =========================================================
            % Show Single mode again
            % =========================================================
            if ~isempty(app.UIFigure) && isvalid(app.UIFigure)
                
                app.UIFigure.Visible = 'on';
                
                figure(app.UIFigure);
                
                drawnow;
                
            end
            
            
            % =========================================================
            % Audit
            % =========================================================
            app.logActivity( ...
                "Navigation", ...
                "Return from child app", ...
                "Source", ...
                "RETURNED", ...
                string(sourceName));
            
        end


        function exportAxesPublication( ...
                app, ...
                sourceAx, ...
                filePath, ...
                dpi, ...
                profile)
            
            if nargin < 6 || strlength(string(profile)) == 0
                profile = "double";
            end
            
            
            % =========================================================
            % Validate
            % =========================================================
            if isempty(sourceAx) || ~isvalid(sourceAx)
                error('Source axis is invalid.');
            end
            
            if strlength(string(filePath)) == 0
                return
            end
            
            if nargin < 5 || isempty(dpi) || ~isfinite(dpi)
                dpi = 600;
            end
            
            
            % =========================================================
            % Publication size in centimeters
            % =========================================================
            switch lower(string(profile))
                
                case "single"
                    figWidth = 8.6;
                    figHeight = 6.5;
                    baseFont = 8;
                    
                case "square"
                    figWidth = 10.0;
                    figHeight = 10.0;
                    baseFont = 8;
                    
                case "wide"
                    figWidth = 17.5;
                    figHeight = 7.5;
                    baseFont = 9;
                    
                otherwise   % double-column
                    figWidth = 17.5;
                    figHeight = 10.5;
                    baseFont = 9;
                    
            end
            
            
            % =========================================================
            % Temporary publication figure
            % =========================================================
            f = figure( ...
                'Visible', 'off', ...
                'Color', 'w', ...
                'Units', 'centimeters', ...
                'Position', [2 2 figWidth figHeight], ...
                'Renderer', 'painters');
            
            
            cleanupObj = onCleanup(@() delete(f));
            
            
            % =========================================================
            % Publication axis
            % =========================================================
            targetAx = axes( ...
                'Parent', f, ...
                'Units', 'normalized', ...
                'Position', [0.15 0.16 0.80 0.76]);
            
            
            % =========================================================
            % Copy graphics objects
            % =========================================================
            copyobj( ...
                flip(sourceAx.Children), ...
                targetAx);
            
            
            % =========================================================
            % Copy important axis properties
            % =========================================================
            targetAx.XLim = sourceAx.XLim;
            targetAx.YLim = sourceAx.YLim;
            
            targetAx.XScale = sourceAx.XScale;
            targetAx.YScale = sourceAx.YScale;
            
            targetAx.XDir = sourceAx.XDir;
            targetAx.YDir = sourceAx.YDir;
            
            targetAx.Box = sourceAx.Box;
            
            targetAx.FontName = 'Arial';
            targetAx.FontSize = baseFont;
            targetAx.LineWidth = 0.8;
            
            targetAx.TickDir = 'out';
            targetAx.TickLength = [0.015 0.015];
            
            
            % =========================================================
            % Labels and title
            % =========================================================
            xlabel( ...
                targetAx, ...
                sourceAx.XLabel.String, ...
                'Interpreter', sourceAx.XLabel.Interpreter, ...
                'FontName', 'Arial', ...
                'FontSize', baseFont + 1);
            
            ylabel( ...
                targetAx, ...
                sourceAx.YLabel.String, ...
                'Interpreter', sourceAx.YLabel.Interpreter, ...
                'FontName', 'Arial', ...
                'FontSize', baseFont + 1);
            
            title( ...
                targetAx, ...
                sourceAx.Title.String, ...
                'Interpreter', sourceAx.Title.Interpreter, ...
                'FontName', 'Arial', ...
                'FontSize', baseFont + 1, ...
                'FontWeight', 'normal');
            
            
            % =========================================================
            % Preserve axis aspect for image/map data
            % =========================================================
            try
                targetAx.DataAspectRatio = ...
                    sourceAx.DataAspectRatio;
                
                targetAx.DataAspectRatioMode = ...
                    sourceAx.DataAspectRatioMode;
            catch
            end
            
            
            % =========================================================
            % Preserve colormap and CLim
            % =========================================================
            try
                colormap(targetAx, colormap(sourceAx));
                
                targetAx.CLim = sourceAx.CLim;
            catch
            end
            
            
            % =========================================================
            % Legend from DisplayName
            % =========================================================
            try
                
                h = findobj( ...
                    targetAx.Children, ...
                    '-property', ...
                    'DisplayName');
                
                names = string( ...
                    get(h, 'DisplayName'));
                
                if any(strlength(names) > 0)
                    
                    legend( ...
                        targetAx, ...
                        'show', ...
                        'Location', ...
                        'best', ...
                        'Box', ...
                        'off', ...
                        'FontSize', ...
                        baseFont);
                    
                end
                
            catch
            end
            
            
            % =========================================================
            % Export
            % =========================================================
            drawnow;
            
            exportgraphics( ...
                f, ...
                filePath, ...
                'Resolution', dpi, ...
                'BackgroundColor', 'white');
            
        end


        function profile = choosePublicationProfile(app)
            
            choices = { ...
                'Single column - 8.6 x 6.5 cm', ...
                'Double column - 17.5 x 10.5 cm', ...
                'Wide spectrum - 17.5 x 7.5 cm', ...
                'Square map - 10 x 10 cm'};
            
            
            [idx, ok] = listdlg( ...
                'PromptString', ...
                'Select publication figure size:', ...
                'SelectionMode', ...
                'single', ...
                'ListString', ...
                choices, ...
                'InitialValue', ...
                2, ...
                'ListSize', ...
                [330 160]);
            
            
            if ~ok
                profile = "";
                return
            end
            
            
            switch idx
                
                case 1
                    profile = "single";
                    
                case 2
                    profile = "double";
                    
                case 3
                    profile = "wide";
                    
                case 4
                    profile = "square";
                    
                otherwise
                    profile = "";
                    
            end
            
        end


        function saveCheckpointFromChild(app, sourceName)
            
            % =========================================================
            % 1. DEFAULT SOURCE NAME
            % =========================================================
            if nargin < 2 || strlength(string(sourceName)) == 0
                sourceName = "Child app";
            end
            
            sourceName = string(sourceName);
            
            % =========================================================
            % 2. SYNCHRONIZE CURRENT DATA
            % =========================================================
            app.syncCurrentDataToAnalysis();
            
            % =========================================================
            % 3. ADD AUDIT ENTRY BEFORE SAVING
            % =========================================================
            app.logActivity( ...
                "Checkpoint", ...
                "Save after child app", ...
                "Source", ...
                "SAVED", ...
                sourceName);
            
            % =========================================================
            % 4. SAVE COMPLETE RESUMABLE SESSION
            %
            % saveSingleHomeSession already saves the activity history.
            % Do not call saveActivityHistory a second time here.
            % =========================================================
            app.saveSingleHomeSession(false);
            
        end
        
        
        function receiveReviewDefinedData( ...
                app, ...
                wn, ...
                reviewedSpectra, ...
                reviewedNames, ...
                reviewMeta, ...
                spectralDef, ...
                peakLibrary)
            
            app.receiveReviewDefinedDataInternal( ...
                wn, ...
                reviewedSpectra, ...
                reviewedNames, ...
                reviewMeta, ...
                spectralDef, ...
                peakLibrary);
            
        end


        % Function 1
        function refreshReviewAfterCosmic(app)
            app.cosmicRemoved = true;
            app.appendHistory("Cosmic removed");
            app.ReviewButtonPushed([]);
        end
        
        % Function 2
        function receiveCheckedData(app, mode, wn, spectra, cube, names, spectralDef)
            
            app.mode = mode;
            app.wn = wn(:);
            
            if nargin >= 7 && ~isempty(spectralDef)
                app.spectralDef = spectralDef;
            end
            
            if nargin >= 6 && ~isempty(names)
                app.names = erase(string(names(:)), ".txt");
            end
            
            switch mode
                
                case 1
                    app.spectra = spectra;
                    app.cube = [];
                    app.rawCube = [];
                    
                    if isempty(app.rawSpectra)
                        app.rawSpectra = spectra;
                    end
                    
                case 2
                    app.cube = cube;
                    app.spectra = [];
            end
            
            app.cosmicRemoved = false;
            app.appendHistory("Data checked");
            
            app.syncCurrentDataToAnalysis();
            
            app.refreshMainAxesAfterChild();
            app.refreshScanAnalysisAfterPreprocess();
            
            drawnow limitrate;
        end
        
        % Function 3
        function receiveBaselineData( ...
                app, ...
                mode, ...
                wn, ...
                correctedSpectra, ...
                correctedCube, ...
                resultInfo)
            
            % =========================================================
            % 1. OPTIONAL RESULT INFORMATION
            % =========================================================
            if nargin < 7 || isempty(resultInfo)
                resultInfo = struct();
            end
            
            % =========================================================
            % 2. VALIDATE MODE
            % =========================================================
            if isempty(mode) || ~ismember(mode, [1 2])
                error('receiveBaselineData:InvalidMode', ...
                    'The returned baseline mode must be 1 or 2.');
            end
            
            % =========================================================
            % 3. VALIDATE WAVENUMBER
            % =========================================================
            if isempty(wn)
                error('receiveBaselineData:EmptyWavenumber', ...
                    'The returned wavenumber axis is empty.');
            end
            
            wn = double(wn(:));
            
            % =========================================================
            % 4. VALIDATE RETURNED DATA
            % =========================================================
            switch mode
                
                case 1
                    if isempty(correctedSpectra)
                        error('receiveBaselineData:EmptySpectra', ...
                            'No baseline-corrected spectra were returned.');
                    end
                    
                    if size(correctedSpectra, 1) ~= numel(wn)
                        error('receiveBaselineData:DimensionMismatch', ...
                            ['The number of rows in the corrected spectra ' ...
                            'does not match the wavenumber axis.']);
                    end
                    
                    correctedCube = [];
                    
                case 2
                    if isempty(correctedCube)
                        error('receiveBaselineData:EmptyCube', ...
                            'No baseline-corrected cube was returned.');
                    end
                    
                    if size(correctedCube, 3) ~= numel(wn)
                        error('receiveBaselineData:DimensionMismatch', ...
                            ['The spectral dimension of the corrected cube ' ...
                            'does not match the wavenumber axis.']);
                    end
                    
                    correctedSpectra = [];
            end
            
            % =========================================================
            % 5. UPDATE PARENT STATE
            % =========================================================
            app.mode = mode;
            app.wn = wn;
            
            app.commitPreprocessStage( ...
                "Baseline", ...
                correctedSpectra, ...
                correctedCube, ...
                "Baseline corrected", ...
                "APPLIED", ...
                true, ...
                resultInfo);
            
            % =========================================================
            % 6. SYNCHRONIZE ANALYSIS DATA
            % =========================================================
            app.syncCurrentDataToAnalysis();
            
            % =========================================================
            % 7. REFRESH DISPLAY
            % =========================================================
            app.refreshMainAxesAfterChild();
            app.refreshScanAnalysisAfterPreprocess();
            
            drawnow limitrate;
            
        end
        
        % Function 4
        function receiveSmoothData( ...
                app, ...
                mode, ...
                wn, ...
                smoothedSpectra, ...
                smoothedCube, ...
                resultInfo)
            
            % =========================================================
            % 1. OPTIONAL RESULT INFORMATION
            % =========================================================
            if nargin < 7 || isempty(resultInfo)
                resultInfo = struct();
            end
            
            % =========================================================
            % 2. VALIDATE MODE
            % =========================================================
            if isempty(mode) || ~ismember(mode, [1 2])
                error('receiveSmoothData:InvalidMode', ...
                    'The returned smoothing mode must be 1 or 2.');
            end
            
            % =========================================================
            % 3. VALIDATE WAVENUMBER
            % =========================================================
            if isempty(wn)
                error('receiveSmoothData:EmptyWavenumber', ...
                    'The returned wavenumber axis is empty.');
            end
            
            wn = double(wn(:));
            
            % =========================================================
            % 4. VALIDATE RETURNED DATA
            % =========================================================
            switch mode
                
                case 1
                    if isempty(smoothedSpectra)
                        error('receiveSmoothData:EmptySpectra', ...
                            'No smoothed spectra were returned.');
                    end
                    
                    if size(smoothedSpectra, 1) ~= numel(wn)
                        error('receiveSmoothData:DimensionMismatch', ...
                            ['The number of rows in the smoothed spectra ' ...
                            'does not match the wavenumber axis.']);
                    end
                    
                    smoothedCube = [];
                    
                case 2
                    if isempty(smoothedCube)
                        error('receiveSmoothData:EmptyCube', ...
                            'No smoothed cube was returned.');
                    end
                    
                    if size(smoothedCube, 3) ~= numel(wn)
                        error('receiveSmoothData:DimensionMismatch', ...
                            ['The spectral dimension of the smoothed cube ' ...
                            'does not match the wavenumber axis.']);
                    end
                    
                    smoothedSpectra = [];
            end
            
            % =========================================================
            % 5. UPDATE PARENT STATE
            % =========================================================
            app.mode = mode;
            app.wn = wn;
            
            app.commitPreprocessStage( ...
                "Smooth", ...
                smoothedSpectra, ...
                smoothedCube, ...
                "Smoothed", ...
                "APPLIED", ...
                true, ...
                resultInfo);
            
            % =========================================================
            % 6. SYNCHRONIZE ANALYSIS DATA
            % =========================================================
            app.syncCurrentDataToAnalysis();
            
            % =========================================================
            % 7. REFRESH DISPLAY
            % =========================================================
            app.refreshMainAxesAfterChild();
            app.refreshScanAnalysisAfterPreprocess();
            
            drawnow limitrate;
            
        end
        
        
        % Function 5:
        function receiveCosmicData( ...
                app, ...
                mode, ...
                wn, ...
                spectra, ...
                cube, ...
                wasCorrected)
            
            app.mode = ...
                mode;
            
            app.wn = ...
                wn(:);
            
            
            if wasCorrected
                
                historyText = ...
                    "Cosmic removed";
                
                statusText = ...
                    "APPLIED";
                
                dataChanged = ...
                    true;
                
            else
                
                historyText = ...
                    "Cosmic checked";
                
                statusText = ...
                    "CHECKED";
                
                dataChanged = ...
                    false;
                
            end
            
            
            app.commitPreprocessStage( ...
                "Cosmic", ...
                spectra, ...
                cube, ...
                historyText, ...
                statusText, ...
                dataChanged);
            
            
            app.cosmicRemoved = ...
                wasCorrected;
            
            
            app.syncCurrentDataToAnalysis();
            
            app.refreshMainAxesAfterChild();
            
            app.refreshScanAnalysisAfterPreprocess();
            
            drawnow limitrate;
            
        end
        
        function syncCurrentDataToAnalysis(app)
            
            if isempty(app.mode) || isempty(app.wn)
                return
            end
            
            switch app.mode
                
                case 1
                    
                    if isempty(app.spectra)
                        return
                    end
                    
                    app.analysisSource = "Current preprocess data";
                    app.analysisWn = app.wn(:);
                    app.analysisSpectra = app.spectra;
                    
                    if ~isempty(app.rawSpectra)
                        app.analysisRawSpectra = app.rawSpectra;
                    else
                        app.analysisRawSpectra = app.spectra;
                    end
                    
                    n = size(app.spectra, 2);
                    
                    if ~isempty(app.names) && numel(app.names) == n
                        app.analysisNames = app.names(:);
                    else
                        app.analysisNames = app.makeDefaultSpectrumNames(n);
                        app.names = app.analysisNames;
                    end
                    
                    if isempty(app.compareGroups)
                        if ~isempty(app.singleData) && isfield(app.singleData, 'GroupIDs')
                            app.buildGroupsFromSingleHome();
                        else
                            app.autoDetectBaseGroups();
                            app.compareGroups = app.baseGroups;
                        end
                    end
                    
                    if app.processHistory == ""
                        app.analysisHistory = "Current preprocess data";
                    else
                        app.analysisHistory = app.processHistory;
                    end
                    
                    app.updateGroupDataDropdown();
                    app.updateSpectralTypeAvailability();
                    
                case 2
                    
                    if isempty(app.cube)
                        return
                    end
                    
                    app.analysisSource = "Current scan preprocess data";
                    app.analysisWn = app.wn(:);
                    app.analysisSpectra = app.cubeToSpectraMatrix(app.cube);
                    
                    if ~isempty(app.rawCube)
                        app.analysisRawSpectra = app.cubeToSpectraMatrix(app.rawCube);
                    else
                        app.analysisRawSpectra = app.analysisSpectra;
                    end
                    
                    n = size(app.analysisSpectra, 2);
                    app.analysisNames = app.makeDefaultSpectrumNames(n);
                    
                    app.autoDetectBaseGroups();
                    
                    if app.processHistory == ""
                        app.analysisHistory = "Current scan preprocess data";
                    else
                        app.analysisHistory = app.processHistory;
                    end
            end
        end
        
        
        function receivePeakLibrary(app, peakLibrary)
            app.peakLibrary = peakLibrary;
            if app.shouldSaveData
                app.saveCurrentSession();
            end
        end
        
        
        function receiveRatioScreeningResults( ...
                app, ...
                selectedPeaks, ...
                resultAll, ...
                resultTop, ...
                ratioValues, ...
                groupLabels, ...
                groupNames, ...
                spectrumNames)
            
            % =========================================================
            % RAM STATE
            %
            % Full screening values stay in RAM for current interaction.
            % They are NOT automatically duplicated into metadata backup.
            % =========================================================
            
            app.ratioScreeningSelectedPeaks = ...
                selectedPeaks;
            
            app.ratioScreeningAll = ...
                resultAll;
            
            app.ratioScreeningTop = ...
                resultTop;
            
            app.ratioScreeningValues = ...
                ratioValues;
            
            app.ratioScreeningGroupLabels = ...
                groupLabels;
            
            app.ratioScreeningGroupNames = ...
                groupNames;
            
            app.ratioScreeningSpectrumNames = ...
                spectrumNames;
            
            app.ratioResults = ...
                resultTop;
            
            app.boxplotResults = ...
                resultTop;
            
            
            % =========================================================
            % METADATA ONLY
            %
            % Do NOT call saveCurrentSession() here.
            % =========================================================
            
            if app.shouldSaveData
                
                app.saveAnalysisMetadata(false);
                
            end
            
        end
        
        function outFolder = getProjectSubFolder(app, category, subCategory)
            if nargin < 3
                subCategory = "";
            end
            if isempty(app.projectFolder)
                baseFolder = uigetdir('', 'Select project/output folder');
                if isequal(baseFolder, 0)
                    outFolder = "";
                    return
                end
                app.projectFolder = baseFolder;
            end
            if strlength(string(subCategory)) > 0
                outFolder = fullfile(app.projectFolder, char(category), char(subCategory));
            else
                outFolder = fullfile(app.projectFolder, char(category));
            end
            if ~exist(outFolder, 'dir')
                mkdir(outFolder);
            end
        end
        
        
        function filePath = askImageFilePath(app, moduleName, defaultName)
            outFolder = app.getProjectSubFolder("Images", moduleName);
            if outFolder == ""
                filePath = "";
                return
            end
            answer = inputdlg( ...
                {'Image file name, without extension:'}, ...
                'Save image', ...
                [1 55], ...
                {char(defaultName)});
            if isempty(answer)
                filePath = "";
                return
            end
            fileName = strtrim(string(answer{1}));
            if fileName == ""
                fileName = string(defaultName);
            end
            fileName = regexprep(fileName, '[\\/:*?"<>|]', '_');
            timestamp = char(datetime('now', 'Format', 'yyyy-MM-dd_HH-mm-ss'));
            filePath = fullfile(outFolder, char(fileName + "_" + timestamp + ".png"));
        end
    end
    
    %% Private functions
    methods (Access = private)
        
        
        function safeSetComponentValue(app, componentName, propName, propValue)
            
            if isprop(app, componentName)
                try
                    comp = app.(componentName);
                    
                    if isvalid(comp) && isprop(comp, propName)
                        comp.(propName) = propValue;
                    end
                catch
                    % Safe ignore.
                end
            end
        end
        
        function prepareSingleHomeUI(app)
            
            app.singleWarningViewMode = "";
            
            % ===== Overall =====
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.FontName = 'Times New Roman';
            app.TextArea_2.FontSize = 14;
            app.TextArea_2.FontWeight = 'bold';
            app.TextArea_2.BackgroundColor = [0.94 0.94 0.94];
            
            app.TextArea_2.Value = { ...
                'OVERALL QC'; ...
                ''; ...
                'Status: NOT RUN'};
            
            % ===== Metadata =====
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.FontName = 'Times New Roman';
            app.TextArea_3.FontSize = 14;
            app.TextArea_3.FontWeight = 'normal';
            app.TextArea_3.BackgroundColor = [0.94 0.94 0.94];
            
            app.TextArea_3.Value = { ...
                'METADATA CHECK'; ...
                ''; ...
                'QC has not been run.'};
            
            % ===== Wavenumber =====
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.FontName = 'Times New Roman';
            app.TextArea_4.FontSize = 12;
            app.TextArea_4.FontWeight = 'normal';
            app.TextArea_4.BackgroundColor = [0.94 0.94 0.94];
            
            app.TextArea_4.Value = { ...
                'WAVENUMBER CHECK'; ...
                ''; ...
                'QC has not been run.'};
            
            % ===== SNR =====
            app.TextArea_5.Editable = 'off';
            app.TextArea_5.FontName = 'Times New Roman';
            app.TextArea_5.FontSize = 12;
            app.TextArea_5.FontWeight = 'normal';
            app.TextArea_5.BackgroundColor = [0.94 0.94 0.94];
            
            app.TextArea_5.Value = { ...
                'SNR CHECK'; ...
                ''; ...
                'QC has not been run.'};
            
            % ===== Buttons =====
            app.CorrectQCdataButton.Enable = 'off';
            app.DetailsButton.Enable = 'off';
            app.SummaryButton.Enable = 'off';
            
            % ===== Plot =====
            cla(app.UIAxes8, 'reset');
            
            app.UIAxes8.FontName = 'Times New Roman';
            app.UIAxes8.FontSize = 16;
            app.UIAxes8.Box = 'on';
            
            grid(app.UIAxes8, 'off');
            
            title(app.UIAxes8, 'Spectral review');
            xlabel(app.UIAxes8, 'Raman shift (cm^{-1})');
            ylabel(app.UIAxes8, 'Intensity (a.u.)');
            
            % ===== Tree =====
            if isprop(app.Tree3, 'Multiselect')
                app.Tree3.Multiselect = 'on';
            end
            
        end
        
        function ok = promoteSingleHomeDataToWorkspace(app)
            
            ok = false;
            
            if isempty(app.singleData) || ...
                    ~isstruct(app.singleData) || ...
                    ~isfield(app.singleData, 'WnCell') || ...
                    ~isfield(app.singleData, 'SpectraCell')
                
                uialert(app.UIFigure, ...
                    'No checked single spectra data found. Please run Access data first.', ...
                    'No data');
                return
            end
            
            % =========================================================
            % BLOCK ONLY WHEN THE SPECTRAL DATA STRUCTURE IS INVALID
            %
            % Label/folder/metadata naming problems must NOT destroy
            % the spectral working matrix.
            % =========================================================
            if ~isempty(app.singleQC) && ...
                    isstruct(app.singleQC) && ...
                    isfield(app.singleQC, 'MetadataDataStructureStatus') && ...
                    string(app.singleQC.MetadataDataStructureStatus) == "FAIL"
                
                app.wn = [];
                app.spectra = [];
                app.rawSpectra = [];
                
                app.names = strings(0,1);
                app.rawNames = strings(0,1);
                
                app.analysisWn = [];
                app.analysisSpectra = [];
                app.analysisRawSpectra = [];
                app.analysisNames = strings(0,1);
                
                app.updateInfoField();
                
                return
            end
            
            nSpectra = numel(app.singleData.SpectraCell);
            
            if nSpectra == 0
                uialert(app.UIFigure, ...
                    'No spectra were loaded.', ...
                    'No data');
                return
            end
            
            refWn = app.singleData.WnCell{1}(:);
            
            if isempty(refWn) || numel(refWn) < 5 || any(~isfinite(refWn))
                uialert(app.UIFigure, ...
                    'Reference wavenumber axis is invalid.', ...
                    'Invalid WN');
                return
            end
            
            canBuild = true;
            
            for k = 1:nSpectra
                
                wnK = app.singleData.WnCell{k}(:);
                yK = app.singleData.SpectraCell{k}(:);
                
                if numel(wnK) ~= numel(refWn) || numel(yK) ~= numel(refWn)
                    canBuild = false;
                    break
                end
                
                if any(~isfinite(wnK)) || any(~isfinite(yK))
                    canBuild = false;
                    break
                end
                
                if max(abs(wnK - refWn), [], 'omitnan') > 1e-6
                    if app.singleWNAccepted
                        % Hard accept: use reference WN axis without interpolation.
                        % Only allowed when all spectra have the same point number.
                        continue
                    else
                        canBuild = false;
                        break
                    end
                end
            end
            
            if ~canBuild
                
                app.wn = [];
                app.spectra = [];
                app.rawSpectra = [];
                
                app.names = strings(0,1);
                app.rawNames = strings(0,1);
                
                app.analysisWn = [];
                app.analysisSpectra = [];
                app.analysisRawSpectra = [];
                app.analysisNames = strings(0,1);
                
                app.updateInfoField();
                
                % No popup.
                % WN summary and Details provide the explanation.
                return
                
            end
            
            X = zeros(numel(refWn), nSpectra);
            
            for k = 1:nSpectra
                X(:,k) = app.singleData.SpectraCell{k}(:);
            end
            
            app.mode = 1;
            app.wn = refWn(:);
            app.spectra = X;
            app.rawSpectra = X;
            app.cube = [];
            app.rawCube = [];
            
            if isfield(app.singleData, 'Names') && numel(app.singleData.Names) == nSpectra
                app.names = string(app.singleData.Names(:));
            else
                app.names = "Spectrum_" + compose("%03d", 1:nSpectra);
                app.names = app.names(:);
            end
            
            app.rawNames = app.names;
            
            % Analysis uses the same checked data immediately.
            app.analysisSource = "Home QC checked data";
            app.analysisWn = app.wn(:);
            app.analysisSpectra = app.spectra;
            app.analysisRawSpectra = app.rawSpectra;
            app.analysisNames = app.names(:);
            
            if app.processHistory == ""
                app.analysisHistory = "Home QC checked data";
            else
                app.analysisHistory = app.processHistory;
            end
            
            app.buildGroupsFromSingleHome();
            
            app.processHistory = "Home QC checked";
            app.updateInfoField();
            app.refreshMainAxesAfterChild();
            
            app.updateGroupDataDropdown();
            app.updateSpectralTypeAvailability();
            
            ok = true;
        end
        
        
        function populateSingleHomeTree(app)
            
            delete(app.Tree3.Children);
            
            
            rootNode = uitreenode(app.Tree3);
            
            rootNode.Text = ...
                'Single spectra data';
            
            rootNode.NodeData = struct( ...
                'Type', "root", ...
                'Folder', char(app.singleInputFolder), ...
                'GroupName', "");
            
            
            if ~isfolder(app.singleInputFolder)
                
                rootNode.Text = ...
                    'Single spectra data - folder not found';
                
                expand(rootNode);
                
                return
                
            end
            
            
            folders = dir(app.singleInputFolder);
            
            folders = folders([folders.isdir]);
            
            folders = folders( ...
                ~ismember({folders.name}, ...
                {'.', '..'}));
            
            
            if isempty(folders)
                
                rootNode.Text = ...
                    'Single spectra data - no groups';
                
                expand(rootNode);
                
                return
                
            end
            
            
            for k = 1:numel(folders)
                
                physicalFolderName = ...
                    string(folders(k).name);
                
                effectiveLabel = ...
                    app.resolveGroupLabelFromRules( ...
                    physicalFolderName);
                
                groupFolder = fullfile( ...
                    app.singleInputFolder, ...
                    folders(k).name);
                
                
                node = uitreenode(rootNode);
                
                
                if any( ...
                        app.singleLastSelectedGroups == ...
                        effectiveLabel)
                    
                    node.Text = ...
                        char("[ACTIVE] " + effectiveLabel);
                    
                else
                    
                    node.Text = ...
                        char(effectiveLabel);
                    
                end
                
                
                node.NodeData = struct( ...
                    'Type', "group", ...
                    'Folder', groupFolder, ...
                    'GroupName', char(effectiveLabel), ...
                    'GroupID', char(effectiveLabel), ...
                    'OriginalFolderName', ...
                    char(physicalFolderName));
                
            end
            
            
            expand(rootNode);
            
            app.restoreLastSelectedGroupsInTree();
            
        end
        
        
        function selectedGroups = getSelectedSingleGroups(app)
            
            selectedGroups = struct( ...
                'Name', {}, ...
                'Folder', {}, ...
                'GroupID', {}, ...
                'OriginalFolderName', {});
            
            
            nodes = app.Tree3.SelectedNodes;
            
            
            if isempty(nodes)
                return
            end
            
            
            for k = 1:numel(nodes)
                
                nd = nodes(k).NodeData;
                
                
                if isempty(nd) || ...
                        ~isstruct(nd)
                    
                    continue
                end
                
                
                if ~isfield(nd, 'Type') || ...
                        string(nd.Type) ~= "group"
                    
                    continue
                end
                
                
                item.Name = ...
                    char(string(nd.GroupName));
                
                item.Folder = ...
                    char(string(nd.Folder));
                
                
                if isfield(nd, 'GroupID')
                    
                    item.GroupID = ...
                        char(string(nd.GroupID));
                    
                else
                    
                    item.GroupID = ...
                        char(string(nd.GroupName));
                    
                end
                
                
                if isfield(nd, ...
                        'OriginalFolderName')
                    
                    item.OriginalFolderName = ...
                        char(string( ...
                        nd.OriginalFolderName));
                    
                else
                    
                    [~, folderName] = ...
                        fileparts(nd.Folder);
                    
                    item.OriginalFolderName = ...
                        folderName;
                    
                end
                
                
                selectedGroups(end+1) = ...
                    item; %#ok<AGROW>
                
            end
            
        end
        
        
        function groupID = makeSafeGroupID(app, groupName)
            
            groupID = string(groupName);
            groupID = strtrim(groupID);
            groupID = regexprep(groupID, '\s+', '_');
            groupID = regexprep(groupID, '[^\w]', '_');
            groupID = regexprep(groupID, '_+', '_');
            groupID = regexprep(groupID, '^_+|_+$', '');
            
            if groupID == ""
                groupID = "Group";
            end
            
            groupID = string(matlab.lang.makeValidName(char(groupID)));
        end
        
        
        function createSingleOutputSessionFolder(app)
            
            % =========================================================
            % 1. Existing active session
            % =========================================================
            if strlength(app.singleOutputSessionFolder) > 0 && ...
                    isfolder(app.singleOutputSessionFolder)
                
                return
                
            end
            
            
            % =========================================================
            % 2. Request session name when necessary
            % =========================================================
            if strlength(app.singleSessionName) == 0
                
                ok = app.requestSingleSessionName();
                
                if ~ok
                    error( ...
                        'RamanCube:SessionCreationCancelled', ...
                        'Session creation was cancelled.');
                end
                
            end
            
            
            % =========================================================
            % 3. Ensure output root exists
            % =========================================================
            if ~isfolder(app.singleOutputRoot)
                
                mkdir(app.singleOutputRoot);
                
            end
            
            
            % =========================================================
            % 4. Ensure Session ID exists
            % =========================================================
            if strlength(app.singleSessionID) == 0
                
                app.singleSessionID = string( ...
                    datetime('now', ...
                    'Format', ...
                    'yyyyMMdd_HHmmss'));
                
            end
            
            
            % =========================================================
            % 5. Build readable unique folder name
            % =========================================================
            safeName = app.makeSafeSessionName( ...
                app.singleSessionName);
            
            folderName = ...
                safeName + "__" + app.singleSessionID;
            
            
            % =========================================================
            % 6. Create SESSION ROOT ONLY
            % =========================================================
            app.singleOutputSessionFolder = ...
                string(fullfile( ...
                app.singleOutputRoot, ...
                folderName));
            
            if ~isfolder(app.singleOutputSessionFolder)
                
                mkdir(app.singleOutputSessionFolder);
                
            end
            
        end
        
        

        function [wnCell, spectraCell, fileNamesCell, filePathsCell, loadIssues] = ...
                loadSingleGroupSpectra(app, groupFolder)
            
            loadIssues = strings(0,1);
            
            wnCell = {};
            spectraCell = {};
            fileNamesCell = strings(0,1);
            filePathsCell = strings(0,1);
            
            groupFolder = char(string(groupFolder));
            
            % =============================================================
            % 1. VALIDATE GROUP FOLDER
            % =============================================================
            
            if ~isfolder(groupFolder)
                
                loadIssues(end+1,1) = ...
                    "Group folder does not exist: " + ...
                    string(groupFolder);
                
                return
            end
            
            % =============================================================
            % 2. LOAD LEGACY TXT SPECTRA
            %
            % One TXT file = one Raman spectrum.
            % =============================================================
            
            txtFiles = ...
                dir( ...
                fullfile( ...
                groupFolder, ...
                '*.txt'));
            
            for k = 1:numel(txtFiles)
                
                fp = ...
                    fullfile( ...
                    groupFolder, ...
                    txtFiles(k).name);
                
                % Do not try to interpret metadata/provenance TXT files
                % generated by the new extraction workflow as Raman spectra.
                lowerName = ...
                    lower( ...
                    string(txtFiles(k).name));
                
                % =============================================================
                % IGNORE NON-SPECTRAL TXT SIDECARS
                %
                % These files contain provenance / QC / membership information,
                % not Raman spectra.
                % =============================================================
                
                if contains(lowerName, "provenance") || ...
                        contains(lowerName, "membership") || ...
                        contains(lowerName, "metadata") || ...
                        contains(lowerName, "readme") || ...
                        contains(lowerName, "manifest") || ...
                        contains(lowerName, "qc") || ...
                        contains(lowerName, "report") || ...
                        contains(lowerName, "summary")
                    
                    continue
                end
                
                try
                    
                    data = ...
                        app.readRamanTxtFlexible(fp);
                    
                    if isempty(data) || ...
                            size(data,2) < 2
                        
                        loadIssues(end+1,1) = ...
                            "Invalid TXT spectrum structure: " + ...
                            string(txtFiles(k).name);
                        
                        continue
                    end
                    
                    wn = ...
                        double(data(:,1));
                    
                    y = ...
                        double(data(:,2));
                    
                    valid = ...
                        isfinite(wn) & ...
                        isfinite(y);
                    
                    wn = ...
                        wn(valid);
                    
                    y = ...
                        y(valid);
                    
                    if numel(wn) < 5 || ...
                            numel(y) < 5
                        
                        loadIssues(end+1,1) = ...
                            "Too few valid points: " + ...
                            string(txtFiles(k).name);
                        
                        continue
                    end
                    
                    wnCell{end+1,1} = ...
                        wn(:); %#ok<AGROW>
                    
                    spectraCell{end+1,1} = ...
                        y(:); %#ok<AGROW>
                    
                    [~, baseName, ~] = ...
                        fileparts( ...
                        txtFiles(k).name);
                    
                    fileNamesCell(end+1,1) = ...
                        string(baseName); %#ok<AGROW>
                    
                    filePathsCell(end+1,1) = ...
                        string(fp); %#ok<AGROW>
                    
                catch ME
                    
                    loadIssues(end+1,1) = ...
                        "Cannot read TXT " + ...
                        string(txtFiles(k).name) + ...
                        ": " + ...
                        string(ME.message);
                end
            end
            
            % =============================================================
            % 3. LOAD NEW MAT DATASETS
            %
            % Expected structure:
            %
            %   Wavenumber   = nWn x 1
            %   MeanSpectra  = nRepresentative x nWn
            %
            % Every MeanSpectra row becomes one single spectrum.
            % =============================================================
            
            matFiles = ...
                dir( ...
                fullfile( ...
                groupFolder, ...
                '*.mat'));
            
            for k = 1:numel(matFiles)
                
                matName = ...
                    string(matFiles(k).name);
                
                lowerMatName = ...
                    lower(strtrim(matName));
                
                
                % =============================================================
                % IGNORE NON-ANALYTICAL MAT SIDECARS
                %
                % spatial_source.mat contains provenance for Spatial distribution.
                % It is NOT a single-spectrum analytical dataset and therefore
                % must never enter metadata load issues.
                % =============================================================
                
                if lowerMatName == "spatial_source.mat" || ...
                        contains(lowerMatName, "metadata") || ...
                        contains(lowerMatName, "provenance") || ...
                        contains(lowerMatName, "manifest")
                    
                    continue
                end
                
                
                fp = ...
                    fullfile( ...
                    groupFolder, ...
                    matFiles(k).name);
                
                try
                    
                    M = ...
                        load(fp);
                    
                    % ---------------------------------------------------------
                    % Require the new minimal analytical MAT structure.
                    % ---------------------------------------------------------
                    
                    if ~isfield(M, 'Wavenumber') || ...
                            ~isfield(M, 'MeanSpectra')
                        
                        loadIssues(end+1,1) = ...
                            "Ignored MAT without Wavenumber + MeanSpectra: " + ...
                            string(matFiles(k).name);
                        
                        continue
                    end
                    
                    wn = ...
                        double( ...
                        M.Wavenumber(:));
                    
                    meanSpectra = ...
                        double( ...
                        M.MeanSpectra);
                    
                    if isempty(wn) || ...
                            numel(wn) < 5
                        
                        loadIssues(end+1,1) = ...
                            "Invalid Wavenumber in MAT: " + ...
                            string(matFiles(k).name);
                        
                        continue
                    end
                    
                    if isempty(meanSpectra) || ...
                            ~ismatrix(meanSpectra)
                        
                        loadIssues(end+1,1) = ...
                            "Invalid MeanSpectra in MAT: " + ...
                            string(matFiles(k).name);
                        
                        continue
                    end
                    
                    % =========================================================
                    % ORIENTATION CHECK
                    %
                    % Preferred:
                    % rows    = spectra
                    % columns = wavenumber
                    %
                    % For compatibility, transpose only when unambiguous.
                    % =========================================================
                    
                    if size(meanSpectra, 2) == numel(wn)
                        
                        % Correct orientation already.
                        
                    elseif size(meanSpectra, 1) == numel(wn) && ...
                            size(meanSpectra, 2) ~= numel(wn)
                        
                        meanSpectra = ...
                            meanSpectra.';
                        
                    else
                        
                        loadIssues(end+1,1) = ...
                            sprintf( ...
                            ['MAT spectral dimension mismatch: %s | ' ...
                            'MeanSpectra=%s | Wavenumber=%d'], ...
                            matFiles(k).name, ...
                            mat2str(size(meanSpectra)), ...
                            numel(wn));
                        
                        continue
                    end
                    
                    numberSpectra = ...
                        size(meanSpectra, 1);
                    
                    if numberSpectra < 1
                        
                        loadIssues(end+1,1) = ...
                            "No representative spectra in MAT: " + ...
                            string(matFiles(k).name);
                        
                        continue
                    end
                    
                    [~, matBaseName, ~] = ...
                        fileparts( ...
                        matFiles(k).name);
                    
                    % =========================================================
                    % 4. EACH MAT ROW BECOMES ONE SPECTRUM
                    % =========================================================
                    
                    for spectrumNumber = 1:numberSpectra
                        
                        y = ...
                            meanSpectra( ...
                            spectrumNumber, :)';
                        
                        valid = ...
                            isfinite(wn) & ...
                            isfinite(y);
                        
                        wnCurrent = ...
                            wn(valid);
                        
                        yCurrent = ...
                            y(valid);
                        
                        if numel(wnCurrent) < 5 || ...
                                numel(yCurrent) < 5
                            
                            loadIssues(end+1,1) = ...
                                sprintf( ...
                                '%s representative %d has too few valid points.', ...
                                matFiles(k).name, ...
                                spectrumNumber);
                            
                            continue
                        end
                        
                        wnCell{end+1,1} = ...
                            wnCurrent(:); %#ok<AGROW>
                        
                        spectraCell{end+1,1} = ...
                            yCurrent(:); %#ok<AGROW>
                        
                        fileNamesCell(end+1,1) = ...
                            string( ...
                            sprintf( ...
                            '%s_rep_%04d', ...
                            matBaseName, ...
                            spectrumNumber)); %#ok<AGROW>
                        
                        filePathsCell(end+1,1) = ...
                            string(fp); %#ok<AGROW>
                    end
                    
                catch ME
                    
                    loadIssues(end+1,1) = ...
                        "Cannot read MAT " + ...
                        string(matFiles(k).name) + ...
                        ": " + ...
                        string(ME.message);
                end
            end
            
            % =============================================================
            % 5. NOTHING LOADABLE
            % =============================================================
            
            if isempty(spectraCell)
                
                loadIssues(end+1,1) = ...
                    "No readable TXT spectra or compatible MAT dataset found in: " + ...
                    string(groupFolder);
            end
            
        end
        
        
        function loadSelectedSingleHomeData(app, selectedGroups)
            
            % Create one organized session folder, but do not save large data now.
            app.createSingleOutputSessionFolder();
            app.projectFolder = char(app.singleOutputSessionFolder);
            [~, sessionName] = ...
                fileparts(app.singleOutputSessionFolder);
            
            app.projectName = ...
                string(sessionName);
            app.shouldSaveData = false;
            
            allWnCell = {};
            allSpectraCell = {};
            allNames = strings(0,1);
            allFilePaths = strings(0,1);
            allGroupLabels = strings(0,1);
            allGroupIDs = strings(0,1);
            
            issueLines = strings(0,1);
            groupRows = table();
            
            for g = 1:numel(selectedGroups)
                
                groupName = ...
                    string(selectedGroups(g).Name);
                
                groupID = ...
                    string(selectedGroups(g).GroupID);
                
                groupFolder = ...
                    string(selectedGroups(g).Folder);
                
                sourceFolderName = ...
                    string(selectedGroups(g).OriginalFolderName);
                
                [wnCell, spectraCell, fileNamesCell, filePathsCell, loadIssues] = ...
                    app.loadSingleGroupSpectra(groupFolder);
                
                issueLines = [issueLines; loadIssues(:)]; %#ok<AGROW>
                
                nLoaded = numel(spectraCell);
                
                groupRows = [ ...
                    groupRows; ...
                    table( ...
                    groupName, ...
                    groupID, ...
                    groupFolder, ...
                    sourceFolderName, ...
                    nLoaded, ...
                    'VariableNames', { ...
                    'DisplayName', ...
                    'GroupID', ...
                    'InputFolder', ...
                    'SourceFolderName', ...
                    'SpectraCount'})]; %#ok<AGROW>
                
                for k = 1:nLoaded
                    allWnCell{end+1,1} = wnCell{k}; %#ok<AGROW>
                    allSpectraCell{end+1,1} = spectraCell{k}; %#ok<AGROW>
                    allNames(end+1,1) = ...
                        string(fileNamesCell(k)); %#ok<AGROW>
                    allFilePaths(end+1,1) = filePathsCell(k); %#ok<AGROW>
                    allGroupLabels(end+1,1) = groupName; %#ok<AGROW>
                    allGroupIDs(end+1,1) = groupID; %#ok<AGROW>
                end
            end
            
            if isempty(allSpectraCell)
                app.TextArea_2.Value = { ...
                    'QC check:', ...
                    '', ...
                    'Status: FAIL', ...
                    'No valid spectra were loaded.'};
                
                uialert(app.UIFigure, ...
                    'No valid spectra were loaded from the selected groups.', ...
                    'Access data failed');
                
                return
            end
            
            app.singleGroupTable = groupRows;
            
            app.singleData = struct();
            app.singleData.WnCell = allWnCell;
            app.singleData.SpectraCell = allSpectraCell;
            app.singleData.Names = allNames(:);
            app.singleData.FilePaths = allFilePaths(:);
            app.singleData.GroupLabels = allGroupLabels(:);
            app.singleData.GroupIDs = allGroupIDs(:);
            app.singleData.LoadIssues = issueLines(:);
            app.singleData.InputFolder = app.singleInputFolder;
            app.singleData.OutputSessionFolder = app.singleOutputSessionFolder;
            app.singleData.CreatedAt = char(datetime('now'));
            
            app.singleLastSelectedGroups = string({selectedGroups.Name})';
            
            app.singleFileTable = table( ...
                allNames(:), ...
                allGroupLabels(:), ...
                allGroupIDs(:), ...
                allFilePaths(:), ...
                'VariableNames', {'SpectrumName','GroupLabel','GroupID','FilePath'});
            
            app.singleQC = app.runSingleInitialQC();
            
            % Link checked Home data into Preprocess and Analysis immediately.
            linkedOK = app.promoteSingleHomeDataToWorkspace();
            
            app.plotSingleHomeSpectra();
            app.updateSingleQCDisplay();
            
            app.singleHomeLoaded = true;
            
            if linkedOK
                
                app.logActivity( ...
                    "Review data", ...
                    "Access data completed", ...
                    "Load + QC", ...
                    string(app.singleQC.Status), ...
                    sprintf( ...
                    "Linked to Preprocess/Analysis. Groups=%d; Spectra=%d.", ...
                    numel(selectedGroups), ...
                    numel(app.singleData.SpectraCell)));
                
            else
                
                app.logActivity( ...
                    "Review data", ...
                    "Access data completed", ...
                    "Load + QC", ...
                    string(app.singleQC.Status), ...
                    sprintf( ...
                    "Loaded but not linked. Groups=%d; Spectra=%d.", ...
                    numel(selectedGroups), ...
                    numel(app.singleData.SpectraCell)));
                
            end
            
            % =========================================================
            % LINK ACCESS DATA TO SPATIAL DISTRIBUTION
            %
            % This does NOT copy Raman cubes.
            % It only resolves scan provenance and refreshes the six
            % spatial dataset dropdowns.
            % =========================================================
            try
                
                app.rebuildSpatialDatasetRegistry();
                
            catch ME
                
                app.logActivity( ...
                    "Spatial distribution", ...
                    "Build spatial dataset registry", ...
                    "Metadata linkage", ...
                    "WARNING", ...
                    string(ME.message));
                
            end
        end
        
        
        function qc = runSingleInitialQC(app)
            
            qc = struct();
            
            % =========================================================
            % 0. INITIAL STATUS
            % =========================================================
            
            qc.Status = "PASS";
            
            qc.MetadataStatus = "PASS";
            qc.WavenumberStatus = "PASS";
            qc.SNRStatus = "PASS";
            
            
            % =========================================================
            % METADATA SUB-CHECKS
            % =========================================================
            
            qc.MetadataDataStructureStatus = "PASS";
            qc.MetadataFolderStatus = "PASS";
            qc.MetadataLabelStatus = "PASS";
            qc.MetadataConsistencyStatus = "PASS";
            
            qc.MetadataDataStructureReasons = strings(0,1);
            qc.MetadataFolderReasons = strings(0,1);
            qc.MetadataLabelReasons = strings(0,1);
            qc.MetadataConsistencyReasons = strings(0,1);
            
            
            % =========================================================
            % GENERAL OUTPUT
            % =========================================================
            
            qc.Issues = strings(0,1);
            
            % Count categories, not number of affected spectra.
            qc.WarningCount = 0;
            qc.FailCount = 0;
            
            qc.MetadataDetails = table();
            qc.WnDetails = table();
            qc.SNRDetails = table();
            
            qc.MetadataSummary = "";
            
            qc.MetadataFileTableMismatch = false;
            
            qc.MetadataLoadIssues = strings(0,1);
            qc.MetadataLoadIssueCount = 0;
            
            qc.MetadataPerSpectrumIssueCount = 0;
            qc.MetadataInvalidLabelCount = 0;
            
            qc.MetadataTooFewPointCount = 0;
            qc.MetadataInvalidSpectrumCount = 0;
            qc.MetadataEmptyGroupCount = 0;
            
            qc.TotalSpectra = 0;
            qc.TotalGroups = 0;
            
            
            % =========================================================
            % WN OUTPUT
            % =========================================================
            
            qc.WnReferenceLength = NaN;
            qc.WnReferenceMin = NaN;
            qc.WnReferenceMax = NaN;
            
            qc.WnMismatchCount = 0;
            qc.WnDuplicateCount = 0;
            qc.WnNonMonotonicCount = 0;
            
            qc.WnLengthMismatchCount = 0;
            qc.WnRangeMismatchCount = 0;
            qc.WnLinearShiftCount = 0;
            qc.WnNonLinearShiftCount = 0;
            
            
            % =========================================================
            % SNR OUTPUT
            % =========================================================
            
            qc.SNRValues = [];
            qc.MedianSNR = NaN;
            qc.MinSNR = NaN;
            qc.MaxSNR = NaN;
            
            qc.LowSNRSpectra = 0;
            qc.InvalidSNRCount = 0;
            
            qc.LowSNRThreshold = app.singleSNRThreshold;
            
            qc.SNRAccepted = app.singleSNRAccepted;
            qc.SNRAcceptNote = app.singleSNRAcceptNote;
            
            
            % =========================================================
            % 1. EXISTENCE OF DATA STRUCTURE
            % =========================================================
            
            if isempty(app.singleData) || ~isstruct(app.singleData)
                
                qc.MetadataDataStructureStatus = "FAIL";
                
                qc.MetadataDataStructureReasons(end+1,1) = ...
                    "No single spectra data structure was found.";
                
                qc.Issues(end+1,1) = ...
                    "No single spectra data structure was found.";
                
                qc.MetadataStatus = "FAIL";
                qc.Status = "FAIL";
                qc.FailCount = 1;
                
                return
            end
            
            
            % =========================================================
            % 2. REQUIRED FIELDS
            % =========================================================
            
            requiredFields = [ ...
                "WnCell", ...
                "SpectraCell", ...
                "Names", ...
                "FilePaths", ...
                "GroupLabels", ...
                "GroupIDs"];
            
            missingFields = strings(0,1);
            
            for i = 1:numel(requiredFields)
                
                fieldName = requiredFields(i);
                
                if ~isfield(app.singleData, char(fieldName))
                    missingFields(end+1,1) = fieldName; %#ok<AGROW>
                end
            end
            
            
            if ~isempty(missingFields)
                
                qc.MetadataDataStructureStatus = "FAIL";
                
                qc.MetadataDataStructureReasons(end+1,1) = ...
                    "Missing required field(s): " + ...
                    strjoin(missingFields, ", ");
                
                qc.Issues(end+1,1) = ...
                    "Missing required metadata field(s): " + ...
                    strjoin(missingFields, ", ");
                
                qc.MetadataStatus = "FAIL";
                qc.Status = "FAIL";
                qc.FailCount = 1;
                
                return
            end
            
            
            % =========================================================
            % 3. NUMBER OF SPECTRA
            % =========================================================
            
            nSpectra = numel(app.singleData.SpectraCell);
            
            qc.TotalSpectra = nSpectra;
            
            if nSpectra == 0
                
                qc.MetadataDataStructureStatus = "FAIL";
                
                qc.MetadataDataStructureReasons(end+1,1) = ...
                    "No spectra were loaded.";
                
                qc.Issues(end+1,1) = ...
                    "No spectra were loaded.";
                
                qc.MetadataStatus = "FAIL";
                qc.Status = "FAIL";
                qc.FailCount = 1;
                
                return
            end
            
            
            % =========================================================
            % 4. GROUP COUNT
            % =========================================================
            
            if isfield(app.singleData, 'GroupIDs') && ...
                    ~isempty(app.singleData.GroupIDs)
                
                qc.TotalGroups = numel(unique( ...
                    string(app.singleData.GroupIDs(:)), ...
                    'stable'));
                
            end
            
            
            % =========================================================
            % 5. METADATA CONSISTENCY CHECK
            % =========================================================
            
            nWn = numel(app.singleData.WnCell);
            nY = numel(app.singleData.SpectraCell);
            nNames = numel(app.singleData.Names);
            nPaths = numel(app.singleData.FilePaths);
            nLabels = numel(app.singleData.GroupLabels);
            nIDs = numel(app.singleData.GroupIDs);
            
            metadataLengths = [ ...
                nWn, ...
                nY, ...
                nNames, ...
                nPaths, ...
                nLabels, ...
                nIDs];
            
            if any(metadataLengths ~= nSpectra)
                
                qc.MetadataConsistencyStatus = "FAIL";
                
                qc.MetadataConsistencyReasons(end+1,1) = sprintf( ...
                    ['Metadata lengths are inconsistent. ' ...
                    'WN=%d, spectra=%d, names=%d, paths=%d, ' ...
                    'labels=%d, IDs=%d.'], ...
                    nWn, nY, nNames, nPaths, nLabels, nIDs);
                
                qc.Issues(end+1,1) = ...
                    "Metadata field lengths are inconsistent.";
                
            end
            
            
            % =========================================================
            % 6. PER-SPECTRUM DATA STRUCTURE CHECK
            % =========================================================
            
            invalidSpectrumMask = false(nSpectra,1);
            tooFewPointMask = false(nSpectra,1);
            
            for k = 1:nSpectra
                
                wnK = app.singleData.WnCell{k};
                yK = app.singleData.SpectraCell{k};
                
                if isempty(wnK) || isempty(yK)
                    
                    invalidSpectrumMask(k) = true;
                    continue
                    
                end
                
                wnK = wnK(:);
                yK = yK(:);
                
                if numel(wnK) ~= numel(yK)
                    
                    invalidSpectrumMask(k) = true;
                    continue
                    
                end
                
                if any(~isfinite(wnK)) || any(~isfinite(yK))
                    
                    invalidSpectrumMask(k) = true;
                    continue
                    
                end
                
                if numel(wnK) < 5
                    
                    tooFewPointMask(k) = true;
                    
                end
                
            end
            
            
            qc.MetadataInvalidSpectrumCount = nnz(invalidSpectrumMask);
            qc.MetadataTooFewPointCount = nnz(tooFewPointMask);
            
            
            if qc.MetadataInvalidSpectrumCount > 0
                
                qc.MetadataDataStructureStatus = "FAIL";
                
                qc.MetadataDataStructureReasons(end+1,1) = sprintf( ...
                    "%d spectrum/spectra have empty, non-finite, or unequal WN/intensity data.", ...
                    qc.MetadataInvalidSpectrumCount);
                
            end
            
            
            if qc.MetadataTooFewPointCount > 0
                
                qc.MetadataDataStructureStatus = "FAIL";
                
                qc.MetadataDataStructureReasons(end+1,1) = sprintf( ...
                    "%d spectrum/spectra contain fewer than 5 valid points.", ...
                    qc.MetadataTooFewPointCount);
                
            end
            
            
            % =========================================================
            % 7. FOLDER / FILE ORGANIZATION CHECK
            % =========================================================
            
            if ~isempty(app.singleGroupTable)
                
                if ismember('SpectraCount', ...
                        app.singleGroupTable.Properties.VariableNames)
                    
                    emptyGroupMask = ...
                        app.singleGroupTable.SpectraCount == 0;
                    
                    qc.MetadataEmptyGroupCount = nnz(emptyGroupMask);
                    
                    if qc.MetadataEmptyGroupCount > 0
                        
                        qc.MetadataFolderStatus = "WARNING";
                        
                        qc.MetadataFolderReasons(end+1,1) = sprintf( ...
                            "%d selected group folder(s) contain no readable spectra.", ...
                            qc.MetadataEmptyGroupCount);
                        
                    end
                end
            end
            
            
            if isfield(app.singleData, 'LoadIssues') && ...
                    ~isempty(app.singleData.LoadIssues)
                
                loadIssues = string(app.singleData.LoadIssues(:));
                loadIssues = strtrim(loadIssues);
                loadIssues = loadIssues(loadIssues ~= "");
                
                if ~isempty(loadIssues)
                    
                    qc.MetadataFolderStatus = "WARNING";
                    
                    qc.MetadataLoadIssues = loadIssues;
                    qc.MetadataLoadIssueCount = numel(loadIssues);
                    
                    qc.MetadataFolderReasons(end+1,1) = sprintf( ...
                        "%d file/folder loading issue(s) were detected.", ...
                        qc.MetadataLoadIssueCount);
                    
                    qc.Issues = [qc.Issues; loadIssues];
                    
                end
                
            end
            
            
            % =========================================================
            % 8. STRICT GROUP FOLDER LABEL CHECK
            % =========================================================
            
            qc.MetadataInvalidLabelCount = 0;
            qc.MetadataPerSpectrumIssueCount = 0;
            
            if isempty(app.singleGroupTable)
                
                qc.MetadataLabelStatus = "WARNING";
                
                qc.MetadataLabelReasons(end+1,1) = ...
                    "Group folder table is unavailable.";
                
                qc.MetadataDetails = table();
                
            else
                
                groupNames = string(app.singleGroupTable.DisplayName(:));
                groupIDs = string(app.singleGroupTable.GroupID(:));
                groupFolders = string(app.singleGroupTable.InputFolder(:));
                
                nGroups = numel(groupIDs);
                
                invalidLabelMask = false(nGroups,1);
                invalidLabelReason = strings(nGroups,1);
                
                for g = 1:nGroups
                    
                    folderLabel = groupIDs(g);
                    
                    if ~app.isStrictGroupLabel(folderLabel)
                        
                        invalidLabelMask(g) = true;
                        
                        invalidLabelReason(g) = ...
                            app.getStrictLabelIssue(folderLabel);
                        
                    end
                    
                end
                
                qc.MetadataInvalidLabelCount = ...
                    nnz(invalidLabelMask);
                
                if qc.MetadataInvalidLabelCount > 0
                    
                    qc.MetadataLabelStatus = "FAIL";
                    
                    qc.MetadataLabelReasons(end+1,1) = sprintf( ...
                        "%d group folder label(s) are invalid.", ...
                        qc.MetadataInvalidLabelCount);
                    
                    qc.MetadataLabelReasons(end+1,1) = ...
                        "Group labels are derived from folder names.";
                    
                    qc.MetadataLabelReasons(end+1,1) = ...
                        "Required structure: underscore-separated label blocks.";
                    
                    qc.Issues(end+1,1) = sprintf( ...
                        "%d group folder label(s) are invalid.", ...
                        qc.MetadataInvalidLabelCount);
                    
                end
                
                qc.MetadataDetails = table( ...
                    groupNames, ...
                    groupIDs, ...
                    groupFolders, ...
                    invalidLabelMask, ...
                    invalidLabelReason, ...
                    'VariableNames', { ...
                    'FolderDisplayName', ...
                    'GroupID', ...
                    'FolderPath', ...
                    'InvalidLabel', ...
                    'Reason'});
                
            end
            
            
            % =========================================================
            % 9. FILE TABLE CONSISTENCY
            % =========================================================
            
            if isempty(app.singleFileTable) || ...
                    height(app.singleFileTable) ~= nSpectra
                
                qc.MetadataFileTableMismatch = true;
                
                if qc.MetadataConsistencyStatus ~= "FAIL"
                    qc.MetadataConsistencyStatus = "WARNING";
                end
                
                qc.MetadataConsistencyReasons(end+1,1) = ...
                    "File table size does not match loaded spectra count.";
                
                qc.Issues(end+1,1) = ...
                    "File table size does not match loaded spectra count.";
                
            end
            
            
            % =========================================================
            % 10. COMBINE METADATA SUB-STATUS
            % =========================================================
            
            metadataSubStatuses = [ ...
                qc.MetadataDataStructureStatus, ...
                qc.MetadataFolderStatus, ...
                qc.MetadataLabelStatus, ...
                qc.MetadataConsistencyStatus];
            
            
            if any(metadataSubStatuses == "FAIL")
                
                qc.MetadataStatus = "FAIL";
                
            elseif any(metadataSubStatuses == "WARNING")
                
                qc.MetadataStatus = "WARNING";
                
            else
                
                qc.MetadataStatus = "PASS";
                
            end
            
            
            % =========================================================
            % 11. WAVENUMBER QC
            % =========================================================
            
            refWn = app.singleData.WnCell{1}(:);
            
            if isempty(refWn) || ...
                    numel(refWn) < 5 || ...
                    any(~isfinite(refWn))
                
                qc.WavenumberStatus = "FAIL";
                
                qc.Issues(end+1,1) = ...
                    "Reference wavenumber axis is invalid.";
                
            else
                
                qc.WnReferenceLength = numel(refWn);
                qc.WnReferenceMin = min(refWn);
                qc.WnReferenceMax = max(refWn);
                
                axisTolerance = 1e-6;
                linearResidualTolerance = 0.02;
                
                mismatch = false(nSpectra,1);
                duplicateWN = false(nSpectra,1);
                nonMonotonicWN = false(nSpectra,1);
                
                lengthMismatchWN = false(nSpectra,1);
                rangeMismatchWN = false(nSpectra,1);
                
                linearShiftWN = false(nSpectra,1);
                nonLinearShiftWN = false(nSpectra,1);
                
                wnIssueType = strings(nSpectra,1);
                
                wnMaxAbsDelta = NaN(nSpectra,1);
                wnMeanDelta = NaN(nSpectra,1);
                wnResidualDelta = NaN(nSpectra,1);
                
                
                refDiff = diff(refWn);
                
                if isempty(refDiff)
                    refDirection = 0;
                else
                    refDirection = sign(median(refDiff, 'omitnan'));
                end
                
                
                for k = 1:nSpectra
                    
                    wnK = app.singleData.WnCell{k}(:);
                    yK = app.singleData.SpectraCell{k}(:);
                    
                    
                    if isempty(wnK) || ...
                            isempty(yK) || ...
                            numel(wnK) ~= numel(yK)
                        
                        mismatch(k) = true;
                        lengthMismatchWN(k) = true;
                        
                        wnIssueType(k) = ...
                            "missing_or_length_mismatch";
                        
                        continue
                    end
                    
                    
                    if any(~isfinite(wnK)) || ...
                            any(~isfinite(yK))
                        
                        mismatch(k) = true;
                        
                        wnIssueType(k) = ...
                            "nonfinite_wn_or_intensity";
                        
                        continue
                    end
                    
                    
                    dwn = diff(wnK);
                    
                    
                    if any(dwn == 0)
                        
                        duplicateWN(k) = true;
                        mismatch(k) = true;
                        
                        wnIssueType(k) = ...
                            "duplicated_wn";
                        
                        continue
                    end
                    
                    
                    dirK = sign(median(dwn, 'omitnan'));
                    
                    
                    if dirK == 0 || ...
                            refDirection == 0 || ...
                            dirK ~= refDirection || ...
                            any(sign(dwn) ~= dirK)
                        
                        nonMonotonicWN(k) = true;
                        mismatch(k) = true;
                        
                        wnIssueType(k) = ...
                            "non_monotonic_wn";
                        
                        continue
                    end
                    
                    
                    if numel(wnK) ~= numel(refWn)
                        
                        mismatch(k) = true;
                        lengthMismatchWN(k) = true;
                        
                        wnIssueType(k) = ...
                            "point_number_mismatch";
                        
                        continue
                    end
                    
                    
                    if abs(min(wnK) - min(refWn)) > axisTolerance || ...
                            abs(max(wnK) - max(refWn)) > axisTolerance
                        
                        rangeMismatchWN(k) = true;
                        
                    end
                    
                    
                    delta = wnK - refWn;
                    
                    maxDelta = max(abs(delta), [], 'omitnan');
                    meanDelta = mean(delta, 'omitnan');
                    
                    residualDelta = max( ...
                        abs(delta - meanDelta), ...
                        [], ...
                        'omitnan');
                    
                    
                    wnMaxAbsDelta(k) = maxDelta;
                    wnMeanDelta(k) = meanDelta;
                    wnResidualDelta(k) = residualDelta;
                    
                    
                    if maxDelta > axisTolerance
                        
                        mismatch(k) = true;
                        
                        if residualDelta <= linearResidualTolerance
                            
                            linearShiftWN(k) = true;
                            wnIssueType(k) = "linear_shift";
                            
                        else
                            
                            nonLinearShiftWN(k) = true;
                            wnIssueType(k) = "nonlinear_shift";
                            
                        end
                        
                    else
                        
                        wnIssueType(k) = "pass";
                        
                    end
                    
                end
                
                
                qc.WnLengthMismatchCount = nnz(lengthMismatchWN);
                qc.WnRangeMismatchCount = nnz(rangeMismatchWN);
                
                qc.WnLinearShiftCount = nnz(linearShiftWN);
                qc.WnNonLinearShiftCount = nnz(nonLinearShiftWN);
                
                qc.WnMismatchCount = nnz(mismatch);
                qc.WnDuplicateCount = nnz(duplicateWN);
                qc.WnNonMonotonicCount = nnz(nonMonotonicWN);
                
                
                qc.WnDetails = table( ...
                    string(app.singleData.Names(:)), ...
                    string(app.singleData.GroupIDs(:)), ...
                    mismatch, ...
                    duplicateWN, ...
                    nonMonotonicWN, ...
                    lengthMismatchWN, ...
                    rangeMismatchWN, ...
                    linearShiftWN, ...
                    nonLinearShiftWN, ...
                    wnIssueType, ...
                    wnMaxAbsDelta, ...
                    wnMeanDelta, ...
                    wnResidualDelta, ...
                    'VariableNames', { ...
                    'SpectrumName', ...
                    'GroupID', ...
                    'Mismatch', ...
                    'DuplicatedWN', ...
                    'NonMonotonicWN', ...
                    'LengthMismatch', ...
                    'RangeMismatch', ...
                    'LinearShift', ...
                    'NonLinearShift', ...
                    'IssueType', ...
                    'MaxAbsDelta_cm1', ...
                    'MeanDelta_cm1', ...
                    'ResidualDelta_cm1'});
                
                
                if qc.WnMismatchCount > 0
                    
                    if app.singleWNAccepted
                        
                        qc.WavenumberStatus = "ACCEPTED";
                        
                        qc.Issues(end+1,1) = sprintf( ...
                            '%d spectra have WN inconsistencies accepted by the user.', ...
                            qc.WnMismatchCount);
                        
                    else
                        
                        qc.WavenumberStatus = "WARNING";
                        
                        qc.Issues(end+1,1) = sprintf( ...
                            '%d spectra have inconsistent wavenumber axes.', ...
                            qc.WnMismatchCount);
                        
                    end
                    
                end
                
            end
            
            
            % =========================================================
            % 12. RAW SNR QC
            % =========================================================
            
            snrVals = NaN(nSpectra,1);
            
            for k = 1:nSpectra
                
                y = double( ...
                    app.singleData.SpectraCell{k}(:));
                
                snrVals(k) = app.estimateRawSNR(y);
                
            end
            
            
            qc.SNRValues = snrVals;
            
            qc.MedianSNR = median( ...
                snrVals, ...
                'omitnan');
            
            qc.MinSNR = min( ...
                snrVals, ...
                [], ...
                'omitnan');
            
            qc.MaxSNR = max( ...
                snrVals, ...
                [], ...
                'omitnan');
            
            
            qc.InvalidSNRCount = ...
                nnz(~isfinite(snrVals));
            
            
            qc.LowSNRSpectra = nnz( ...
                snrVals < qc.LowSNRThreshold | ...
                ~isfinite(snrVals));
            
            
            qc.SNRDetails = table( ...
                string(app.singleData.Names(:)), ...
                string(app.singleData.GroupIDs(:)), ...
                snrVals, ...
                snrVals < qc.LowSNRThreshold | ~isfinite(snrVals), ...
                'VariableNames', { ...
                'SpectrumName', ...
                'GroupID', ...
                'RawSNR', ...
                'LowOrInvalidSNR'});
            
            
            if qc.LowSNRSpectra > 0
                
                if app.singleSNRAccepted
                    
                    qc.SNRStatus = "ACCEPTED";
                    
                    qc.Issues(end+1,1) = sprintf( ...
                        '%d low/invalid-SNR spectra accepted by user.', ...
                        qc.LowSNRSpectra);
                    
                else
                    
                    qc.SNRStatus = "WARNING";
                    
                    qc.Issues(end+1,1) = sprintf( ...
                        '%d spectra have low or invalid raw SNR.', ...
                        qc.LowSNRSpectra);
                    
                end
                
            end
            
            
            % =========================================================
            % 13. CATEGORY COUNTS
            % =========================================================
            
            failStatuses = [ ...
                qc.MetadataStatus, ...
                qc.WavenumberStatus, ...
                qc.SNRStatus] == "FAIL";
            
            warningStatuses = [ ...
                qc.MetadataStatus, ...
                qc.WavenumberStatus, ...
                qc.SNRStatus] == "WARNING";
            
            
            qc.FailCount = nnz(failStatuses);
            qc.WarningCount = nnz(warningStatuses);
            
            
            % =========================================================
            % 14. FINAL STATUS
            % =========================================================
            
            if qc.FailCount > 0
                
                qc.Status = "FAIL";
                
            elseif qc.WarningCount > 0
                
                qc.Status = "WARNING";
                
            elseif any([ ...
                    qc.WavenumberStatus, ...
                    qc.SNRStatus] == "ACCEPTED")
                
                qc.Status = "ACCEPTED";
                
            else
                
                qc.Status = "PASS";
                
            end
            
        end
        
        
        function snrVal = estimateRawSNR(app, y)
            
            y = double(y(:));
            y = y(isfinite(y));
            
            if numel(y) < 10
                snrVal = NaN;
                return
            end
            
            signalVal = prctile(y, 95) - prctile(y, 5);
            
            dy = diff(y);
            noiseVal = 1.4826 * median(abs(dy - median(dy, 'omitnan')), 'omitnan');
            
            if ~isfinite(noiseVal) || noiseVal <= 0
                noiseVal = std(dy, 0, 'omitnan');
            end
            
            if ~isfinite(noiseVal) || noiseVal <= 0
                snrVal = NaN;
            else
                snrVal = signalVal / noiseVal;
            end
        end
        
        
        function buildSingleMatrixIfPossible(app)
            
            app.promoteSingleHomeDataToWorkspace();
        end
        
        
        function buildGroupsFromSingleHome(app)
            
            if isempty(app.singleData) || ~isfield(app.singleData, 'GroupIDs')
                return
            end
            
            labels = string(app.singleData.GroupIDs(:));
            displayLabels = string(app.singleData.GroupLabels(:));
            uniqueIDs = unique(labels, 'stable');
            
            bg = struct('Name', {}, 'Indices', {}, 'Count', {});
            cg = struct('Name', {}, 'BaseGroupNames', {}, 'Indices', {}, 'Count', {});
            
            for g = 1:numel(uniqueIDs)
                idx = find(labels == uniqueIDs(g));
                displayName = displayLabels(idx(1));
                
                bg(g).Name = char(uniqueIDs(g));
                bg(g).Indices = idx(:)';
                bg(g).Count = numel(idx);
                
                cg(g).Name = char(uniqueIDs(g));
                cg(g).BaseGroupNames = displayName;
                cg(g).Indices = idx(:)';
                cg(g).Count = numel(idx);
            end
            
            app.baseGroups = bg;
            app.compareGroups = cg;
            
            app.updateGroupDataDropdown();
            app.updateSpectralTypeAvailability();
        end
        
        
        function plotSingleHomeSpectra(app)
            
            cla(app.UIAxes8, 'reset');
            
            app.UIAxes8.Box = 'on';
            app.UIAxes8.FontName = ...
                'Times New Roman';
            app.UIAxes8.FontSize = 16;
            
            grid(app.UIAxes8, 'off');
            
            
            % =====================================================
            % TOTAL LOADED
            % =====================================================
            
            totalLoaded = 0;
            
            if ~isempty(app.singleData) && ...
                    isstruct(app.singleData) && ...
                    isfield(app.singleData, 'SpectraCell')
                
                totalLoaded = ...
                    numel(app.singleData.SpectraCell);
                
            end
            
            
            % =====================================================
            % COMMON MATRIX MODE
            % =====================================================
            
            if ~isempty(app.wn) && ...
                    ~isempty(app.spectra)
                
                nPlotted = ...
                    size(app.spectra,2);
                
                
                plot( ...
                    app.UIAxes8, ...
                    app.wn, ...
                    app.spectra, ...
                    'LineWidth', 0.6);
                
                
                xlabel( ...
                    app.UIAxes8, ...
                    'Raman shift (cm^{-1})');
                
                ylabel( ...
                    app.UIAxes8, ...
                    'Intensity (a.u.)');
                
                
                title( ...
                    app.UIAxes8, ...
                    sprintf( ...
                    'Spectral review — plotted %d / %d loaded spectra', ...
                    nPlotted, ...
                    totalLoaded), ...
                    'Interpreter', 'none');
                
                
                legend(app.UIAxes8, 'off');
                
                
                xlim( ...
                    app.UIAxes8, ...
                    [min(app.wn), max(app.wn)]);
                
                
                return
                
            end
            
            
            % =====================================================
            % CELL PREVIEW MODE
            % =====================================================
            
            if totalLoaded == 0
                
                title( ...
                    app.UIAxes8, ...
                    'No spectra');
                
                return
                
            end
            
            
            hold(app.UIAxes8, 'on');
            
            
            nPlotted = 0;
            
            validMin = [];
            validMax = [];
            
            
            for k = 1:totalLoaded
                
                wnK = ...
                    app.singleData.WnCell{k};
                
                yK = ...
                    app.singleData.SpectraCell{k};
                
                
                if isempty(wnK) || ...
                        isempty(yK)
                    
                    continue
                    
                end
                
                
                if numel(wnK) ~= ...
                        numel(yK)
                    
                    continue
                    
                end
                
                
                valid = ...
                    isfinite(wnK) & ...
                    isfinite(yK);
                
                
                if nnz(valid) < 2
                    continue
                end
                
                
                wnPlot = ...
                    wnK(valid);
                
                yPlot = ...
                    yK(valid);
                
                
                plot( ...
                    app.UIAxes8, ...
                    wnPlot, ...
                    yPlot, ...
                    'LineWidth', 0.5);
                
                
                nPlotted = ...
                    nPlotted + 1;
                
                validMin(end+1) = ...
                    min(wnPlot); %#ok<AGROW>
                
                validMax(end+1) = ...
                    max(wnPlot); %#ok<AGROW>
                
            end
            
            
            hold(app.UIAxes8, 'off');
            
            
            xlabel( ...
                app.UIAxes8, ...
                'Raman shift (cm^{-1})');
            
            ylabel( ...
                app.UIAxes8, ...
                'Intensity (a.u.)');
            
            
            title( ...
                app.UIAxes8, ...
                sprintf( ...
                'Spectral review — plotted %d / %d loaded spectra', ...
                nPlotted, ...
                totalLoaded), ...
                'Interpreter', 'none');
            
            
            legend(app.UIAxes8, 'off');
            
            
            if ~isempty(validMin) && ...
                    ~isempty(validMax)
                
                xlim( ...
                    app.UIAxes8, ...
                    [min(validMin), ...
                    max(validMax)]);
                
            end
            
        end
        
        
        function updateSingleQCDisplay(app)
            
            % =========================================================
            % NO QC RESULT
            % =========================================================
            
            if isempty(app.singleQC) || ...
                    ~isstruct(app.singleQC) || ...
                    ~isfield(app.singleQC, 'Status')
                
                app.TextArea_2.Value = { ...
                    'OVERALL QC'; ...
                    ''; ...
                    'Status: NOT RUN'};
                
                app.TextArea_3.Value = { ...
                    'METADATA CHECK'; ...
                    ''; ...
                    'QC has not been run.'};
                
                app.TextArea_4.Value = { ...
                    'WAVENUMBER CHECK'; ...
                    ''; ...
                    'QC has not been run.'};
                
                app.TextArea_5.Value = { ...
                    'SNR CHECK'; ...
                    ''; ...
                    'QC has not been run.'};
                
                app.TextArea_2.BackgroundColor = ...
                    [0.94 0.94 0.94];
                
                app.TextArea_3.BackgroundColor = ...
                    [0.94 0.94 0.94];
                
                app.TextArea_4.BackgroundColor = ...
                    [0.94 0.94 0.94];
                
                app.TextArea_5.BackgroundColor = ...
                    [0.94 0.94 0.94];
                
                app.CorrectQCdataButton.Enable = 'off';
                app.DetailsButton.Enable = 'off';
                app.SummaryButton.Enable = 'off';
                
                return
                
            end
            
            
            qc = app.singleQC;
            
            
            % =========================================================
            % OVERALL
            % =========================================================
            
            app.TextArea_2.BackgroundColor = ...
                app.getQCBackgroundColor(qc.Status);
            
            overallLines = strings(0,1);
            
            overallLines(end+1,1) = ...
                "OVERALL QC: " + string(qc.Status);
            
            overallLines(end+1,1) = "";
            
            overallLines(end+1,1) = sprintf( ...
                "Groups: %d", ...
                qc.TotalGroups);
            
            overallLines(end+1,1) = sprintf( ...
                "Spectra: %d", ...
                qc.TotalSpectra);
            
            overallLines(end+1,1) = sprintf( ...
                "Fail checks: %d | Warning checks: %d", ...
                qc.FailCount, ...
                qc.WarningCount);
            
            app.TextArea_2.Value = cellstr(overallLines);
            
            
            % =========================================================
            % METADATA SUMMARY
            % =========================================================
            
            app.TextArea_3.BackgroundColor = ...
                app.getQCBackgroundColor(qc.MetadataStatus);
            
            metaLines = strings(0,1);
            
            metaLines(end+1,1) = ...
                "METADATA CHECK: " + ...
                string(qc.MetadataStatus);
            
            metaLines(end+1,1) = ...
                "------------------------------";
            
            metaLines(end+1,1) = ...
                "  " + ...
                app.getQCMark(qc.MetadataDataStructureStatus) + ...
                " Data structure";
            
            metaLines(end+1,1) = ...
                "  " + ...
                app.getQCMark(qc.MetadataFolderStatus) + ...
                " Folder organization";
            
            metaLines(end+1,1) = ...
                "  " + ...
                app.getQCMark(qc.MetadataLabelStatus) + ...
                " Group folder label";
            
            metaLines(end+1,1) = ...
                "  " + ...
                app.getQCMark(qc.MetadataConsistencyStatus) + ...
                " Metadata consistency";
            
            
            metaReasons = strings(0,1);
            
            
            if qc.MetadataDataStructureStatus ~= "PASS"
                
                metaReasons = [ ...
                    metaReasons; ...
                    qc.MetadataDataStructureReasons(:)];
                
            end
            
            
            if qc.MetadataFolderStatus ~= "PASS"
                
                metaReasons = [ ...
                    metaReasons; ...
                    qc.MetadataFolderReasons(:)];
                
            end
            
            
            if qc.MetadataLabelStatus ~= "PASS"
                
                metaReasons = [ ...
                    metaReasons; ...
                    qc.MetadataLabelReasons(:)];
                
            end
            
            
            if qc.MetadataConsistencyStatus ~= "PASS"
                
                metaReasons = [ ...
                    metaReasons; ...
                    qc.MetadataConsistencyReasons(:)];
                
            end
            
            
            metaReasons = strtrim(metaReasons);
            metaReasons = metaReasons(metaReasons ~= "");
            
            
            metaLines(end+1,1) = "";
            
            
            if isempty(metaReasons)
                
                metaLines(end+1,1) = ...
                    "  Reason: No issue detected.";
                
            else
                
                metaLines(end+1,1) = ...
                    "  Reason:";
                
                nShow = min(2, numel(metaReasons));
                
                for i = 1:nShow
                    
                    metaLines(end+1,1) = ...
                        "  * " + metaReasons(i);
                    
                end
                
                if numel(metaReasons) > nShow
                    
                    metaLines(end+1,1) = sprintf( ...
                        "  * +%d more issue(s)", ...
                        numel(metaReasons) - nShow);
                    
                end
                
            end
            
            
            metaLines(end+1,1) = "";
            
            metaLines(end+1,1) = ...
                "  Details: use Details button.";
            
            app.TextArea_3.Value = cellstr(metaLines);
            
            
            % =========================================================
            % WAVENUMBER SUMMARY
            % =========================================================
            
            app.TextArea_4.BackgroundColor = ...
                app.getQCBackgroundColor(qc.WavenumberStatus);
            
            wnLines = strings(0,1);
            
            wnLines(end+1,1) = ...
                "WAVENUMBER CHECK: " + ...
                string(qc.WavenumberStatus);
            
            wnLines(end+1,1) = ...
                "------------------------------";
            
            
            if isfinite(qc.WnReferenceLength)
                
                wnLines(end+1,1) = sprintf( ...
                    "  Reference points: %d", ...
                    qc.WnReferenceLength);
                
            else
                
                wnLines(end+1,1) = ...
                    "  Reference points: N/A";
                
            end
            
            
            wnLines(end+1,1) = sprintf( ...
                "  Axis mismatch: %d", ...
                qc.WnMismatchCount);
            
            wnLines(end+1,1) = sprintf( ...
                "  Linear / nonlinear: %d / %d", ...
                qc.WnLinearShiftCount, ...
                qc.WnNonLinearShiftCount);
            
            wnLines(end+1,1) = sprintf( ...
                "  Point mismatch: %d", ...
                qc.WnLengthMismatchCount);
            
            
            wnLines(end+1,1) = "";
            
            
            switch string(qc.WavenumberStatus)
                
                case "PASS"
                    
                    wnLines(end+1,1) = ...
                        "  Reason: No axis inconsistency detected.";
                    
                case "ACCEPTED"
                    
                    wnLines(end+1,1) = ...
                        "  Reason: WN warning accepted by user.";
                    
                case "WARNING"
                    
                    wnLines(end+1,1) = ...
                        "  Reason:";
                    
                    wnLines(end+1,1) = sprintf( ...
                        "  * %d spectra differ from the reference axis.", ...
                        qc.WnMismatchCount);
                    
                otherwise
                    
                    wnLines(end+1,1) = ...
                        "  Reason: Invalid reference axis.";
                    
            end
            
            
            wnLines(end+1,1) = "";
            
            wnLines(end+1,1) = ...
                "  Details: use Details button.";
            
            app.TextArea_4.Value = cellstr(wnLines);
            
            
            % =========================================================
            % SNR SUMMARY
            % =========================================================
            
            app.TextArea_5.BackgroundColor = ...
                app.getQCBackgroundColor(qc.SNRStatus);
            
            snrLines = strings(0,1);
            
            snrLines(end+1,1) = ...
                "SNR CHECK: " + ...
                string(qc.SNRStatus);
            
            snrLines(end+1,1) = ...
                "------------------------------";
            
            
            snrLines(end+1,1) = sprintf( ...
                "  Threshold: %.2f", ...
                qc.LowSNRThreshold);
            
            
            if isfinite(qc.MedianSNR)
                
                snrLines(end+1,1) = sprintf( ...
                    "  Median SNR: %.2f", ...
                    qc.MedianSNR);
                
            else
                
                snrLines(end+1,1) = ...
                    "  Median SNR: N/A";
                
            end
            
            
            if isfinite(qc.MinSNR)
                
                snrLines(end+1,1) = sprintf( ...
                    "  Minimum SNR: %.2f", ...
                    qc.MinSNR);
                
            else
                
                snrLines(end+1,1) = ...
                    "  Minimum SNR: N/A";
                
            end
            
            
            snrLines(end+1,1) = sprintf( ...
                "  Low / invalid: %d", ...
                qc.LowSNRSpectra);
            
            
            snrLines(end+1,1) = "";
            
            
            if qc.LowSNRSpectra == 0
                
                snrLines(end+1,1) = ...
                    "  Reason: No low-SNR spectrum detected.";
                
            elseif string(qc.SNRStatus) == "ACCEPTED"
                
                snrLines(end+1,1) = ...
                    "  Reason: Low-SNR warning accepted by user.";
                
            else
                
                snrLines(end+1,1) = ...
                    "  Reason:";
                
                snrLines(end+1,1) = sprintf( ...
                    "  * %d spectra are below the current threshold.", ...
                    qc.LowSNRSpectra);
                
            end
            
            
            snrLines(end+1,1) = "";
            
            snrLines(end+1,1) = ...
                "  Details: use Details button.";
            
            app.TextArea_5.Value = cellstr(snrLines);
            
            
            % =========================================================
            % BUTTON STATES
            % =========================================================
            
            app.SummaryButton.Enable = 'on';
            
            
            hasDetail = ...
                string(qc.MetadataStatus) ~= "PASS" || ...
                string(qc.WavenumberStatus) ~= "PASS" || ...
                any(string(qc.SNRStatus) == ["WARNING", "ACCEPTED"]);
            
            
            if hasDetail
                app.DetailsButton.Enable = 'on';
            else
                app.DetailsButton.Enable = 'off';
            end
            
            
            needsCorrection = ...
                string(qc.MetadataStatus) ~= "PASS" || ...
                string(qc.WavenumberStatus) == "WARNING" || ...
                string(qc.WavenumberStatus) == "FAIL" || ...
                string(qc.SNRStatus) == "WARNING";
            
            
            if needsCorrection
                app.CorrectQCdataButton.Enable = 'on';
            else
                app.CorrectQCdataButton.Enable = 'off';
            end
            
        end
        
        
        function saveSingleQCReport(app, showMessage)
            
            if nargin < 2
                showMessage = true;
            end
            
            if isempty(app.singleOutputSessionFolder)
                app.createSingleOutputSessionFolder();
            end
            
            qcFolder = fullfile(app.singleOutputSessionFolder, '01_QC');
            tableFolder = fullfile(app.singleOutputSessionFolder, '01_QC');
            
            if ~isfolder(qcFolder)
                mkdir(qcFolder);
            end
            
            if ~isfolder(tableFolder)
                mkdir(tableFolder);
            end
            
            qcFile = fullfile(qcFolder, 'QC_summary.txt');
            
            fid = fopen(qcFile, 'w');
            
            if fid == -1
                uialert(app.UIFigure, 'Cannot write QC summary file.', 'QC save error');
                return
            end
            
            cleaner = onCleanup(@() fclose(fid));
            
            fprintf(fid, 'SINGLE SPECTRA QC SUMMARY\n');
            fprintf(fid, '=========================\n\n');
            fprintf(fid, 'Date: %s\n', char(datetime('now')));
            fprintf(fid, 'User: %s\n', char(app.homeUser));
            fprintf(fid, 'Input folder: %s\n', char(app.singleInputFolder));
            fprintf(fid, 'Output folder: %s\n\n', char(app.singleOutputSessionFolder));
            
            if ~isempty(app.singleQC) && isstruct(app.singleQC) && isfield(app.singleQC, 'Status')
                
                qc = app.singleQC;
                
                fprintf(fid, 'Status: %s\n\n', char(qc.Status));
                fprintf(fid, 'Selected groups: %d\n', qc.TotalGroups);
                fprintf(fid, 'Total spectra: %d\n\n', qc.TotalSpectra);
                
                fprintf(fid, 'Metadata structure: %s\n', char(qc.MetadataStatus));
                fprintf(fid, 'Wavenumber consistency: %s\n', char(qc.WavenumberStatus));
                fprintf(fid, 'Raw SNR: %s\n\n', char(qc.SNRStatus));
                
                fprintf(fid, 'Reference WN length: %d\n', qc.WnReferenceLength);
                fprintf(fid, 'Reference WN range: %.6f - %.6f cm^-1\n', qc.WnReferenceMin, qc.WnReferenceMax);
                fprintf(fid, 'WN mismatch count: %d\n', qc.WnMismatchCount);
                
                if isfield(qc, 'WnDuplicateCount')
                    fprintf(fid, 'Duplicated WN count: %d\n', qc.WnDuplicateCount);
                end
                
                if isfield(qc, 'WnNonMonotonicCount')
                    fprintf(fid, 'Non-monotonic WN count: %d\n', qc.WnNonMonotonicCount);
                end
                
                fprintf(fid, '\nMedian SNR: %.4f\n', qc.MedianSNR);
                fprintf(fid, 'Min SNR: %.4f\n', qc.MinSNR);
                fprintf(fid, 'Max SNR: %.4f\n', qc.MaxSNR);
                fprintf(fid, 'Low/invalid SNR spectra: %d\n', qc.LowSNRSpectra);
                
                if isfield(qc, 'InvalidSNRCount')
                    fprintf(fid, 'Invalid SNR count: %d\n', qc.InvalidSNRCount);
                end
                
                fprintf(fid, '\nIssues:\n');
                
                if ~isfield(qc, 'Issues') || isempty(qc.Issues)
                    fprintf(fid, 'None\n');
                else
                    issues = string(qc.Issues(:));
                    issues = issues(issues ~= "");
                    
                    if isempty(issues)
                        fprintf(fid, 'None\n');
                    else
                        for i = 1:numel(issues)
                            fprintf(fid, '- %s\n', char(issues(i)));
                        end
                    end
                end
            else
                fprintf(fid, 'QC has not been run yet.\n');
            end
            
            if ~isempty(app.singleGroupTable)
                writetable(app.singleGroupTable, fullfile(tableFolder, 'group_label_table.csv'));
            end
            
            if ~isempty(app.singleFileTable)
                writetable(app.singleFileTable, fullfile(tableFolder, 'file_label_table.csv'));
            end
            
            if ~isempty(app.singleQC) && isstruct(app.singleQC) && ...
                    isfield(app.singleQC, 'SNRValues') && ~isempty(app.singleFileTable)
                
                snrTable = app.singleFileTable;
                
                if height(snrTable) == numel(app.singleQC.SNRValues)
                    snrTable.RawSNR = app.singleQC.SNRValues(:);
                    writetable(snrTable, fullfile(tableFolder, 'SNR_table.csv'));
                end
            end
            
            if showMessage
                msgbox(sprintf('QC report saved:\n%s', qcFile), 'QC report');
            end
        end
        
        
        function saveSingleHomeSession(app, showMessage)
            
            if nargin < 2
                showMessage = true;
            end
            
            
            % =========================================================
            % Ensure session root exists
            % =========================================================
            if strlength(app.singleOutputSessionFolder) == 0 || ...
                    ~isfolder(app.singleOutputSessionFolder)
                
                app.createSingleOutputSessionFolder();
                
            end
            
            
            % =========================================================
            % Audit/session folder
            % =========================================================
            sessionFolder = fullfile( ...
                app.singleOutputSessionFolder, ...
                '05_Report_Audit');
            
            if ~isfolder(sessionFolder)
                mkdir(sessionFolder);
            end
            
            
            % =========================================================
            % SESSION STRUCTURE
            % =========================================================
            S = struct();
            
            S.savedAt = char(datetime('now'));
            S.appVersion = 'single_spectra_session_v4_ratio_reference_pca';
            
            
            % =========================================================
            % SESSION IDENTITY
            % =========================================================
            S.singleSessionName = ...
                app.singleSessionName;
            
            S.singleSessionID = ...
                app.singleSessionID;
            
            
            % =========================================================
            % HOME CONTEXT
            % =========================================================
            S.homeSession = app.homeSession;
            S.homeUser = app.homeUser;
            S.homeRole = app.homeRole;
            S.homeEmail = app.homeEmail;
            
            
            % =========================================================
            % PATHS
            % =========================================================
            S.singleInputFolder = ...
                app.singleInputFolder;
            
            S.singleOutputRoot = ...
                app.singleOutputRoot;
            
            S.singleOutputSessionFolder = ...
                app.singleOutputSessionFolder;
            
            
            % =========================================================
            % HOME/QC STATE
            % =========================================================
            S.singleHomeLoaded = ...
                app.singleHomeLoaded;
            
            S.singleHomeCorrected = ...
                app.singleHomeCorrected;
            
            S.singleData = ...
                app.singleData;
            
            S.singleQC = ...
                app.singleQC;
            
            S.singleGroupTable = ...
                app.singleGroupTable;
            
            S.singleFileTable = ...
                app.singleFileTable;
            
            S.singleLastSelectedGroups = ...
                app.singleLastSelectedGroups;
            
            
            % =========================================================
            % CURRENT WORKING DATA
            % =========================================================
            S.mode = app.mode;
            
            S.wn = app.wn;
            
            S.spectra = app.spectra;
            
            S.rawSpectra = app.rawSpectra;
            
            S.names = app.names;
            
            S.rawNames = app.rawNames;
            
            
            % =========================================================
            % PREPROCESS STATE
            % =========================================================
            S.processHistory = ...
                app.processHistory;
            
            S.cosmicRemoved = ...
                app.cosmicRemoved;
            
            S.spectralDef = ...
                app.spectralDef;
            
            S.peakLibrary = ...
                app.peakLibrary;
            
            S.dataReviewState = ...
                app.dataReviewState;
            
            
            % =========================================================
            % ANALYSIS DATA
            % =========================================================
            S.analysisSource = ...
                app.analysisSource;
            
            S.analysisWn = ...
                app.analysisWn;
            
            S.analysisSpectra = ...
                app.analysisSpectra;
            
            S.analysisRawSpectra = ...
                app.analysisRawSpectra;
            
            S.analysisNames = ...
                app.analysisNames;
            
            S.analysisHistory = ...
                app.analysisHistory;
            
            
            % =========================================================
            % GROUPS
            % =========================================================
            S.baseGroups = ...
                app.baseGroups;
            
            S.compareGroups = ...
                app.compareGroups;
            
            
            % =========================================================
            % RATIO SCREENING
            % =========================================================
            S.ratioFormula = ...
                app.ratioFormula;
            
            S.ratioBands = ...
                app.ratioBands;
            
            S.ratioResults = ...
                app.ratioResults;
            
            S.boxplotResults = ...
                app.boxplotResults;
            
            S.ratioDefinitions = ...
                app.ratioDefinitions;
            
            S.ratioScreeningSelectedPeaks = ...
                app.ratioScreeningSelectedPeaks;
            
            S.ratioScreeningAll = ...
                app.ratioScreeningAll;
            
            S.ratioScreeningTop = ...
                app.ratioScreeningTop;
            
            S.ratioScreeningValues = ...
                app.ratioScreeningValues;
            
            S.ratioScreeningGroupLabels = ...
                app.ratioScreeningGroupLabels;
            
            S.ratioScreeningGroupNames = ...
                app.ratioScreeningGroupNames;
            
            S.ratioScreeningSpectrumNames = ...
                app.ratioScreeningSpectrumNames;
            
            
            % =========================================================
            % INTEGRATED INTERPRETATION
            %
            % Registry only.
            % Temporary interpretSession is deliberately NOT saved.
            % =========================================================
            
            app.ensureInterpretationRegistrySchema();
            
            S.interpretationMetadataVersion = ...
                app.interpretationMetadataVersion;
            
            S.interpretationRegistry = ...
                app.interpretationRegistry;
            
            S.summarySelectedEvidenceID = ...
                app.summarySelectedEvidenceID;
            
            
            % =========================================================
            % PCA
            % =========================================================
            S.pcaInputType = ...
                app.pcaInputType;
            
            S.pcaVariableNames = ...
                app.pcaVariableNames;
            
            S.pcaSourceX = ...
                app.pcaSourceX;
            
            S.pcaX = ...
                app.pcaX;
            
            S.pcaWn = ...
                app.pcaWn;
            
            S.pcaScores = ...
                app.pcaScores;
            
            S.pcaLoadings = ...
                app.pcaLoadings;
            
            S.pcaExplained = ...
                app.pcaExplained;
            
            S.pcaGroupLabels = ...
                app.pcaGroupLabels;
            
            S.pcaGroupNames = ...
                app.pcaGroupNames;
            
            S.pcaSpectrumNames = ...
                app.pcaSpectrumNames;
            
            S.pcaNormMethod = ...
                app.pcaNormMethod;
            
            
            % =========================================================
            % REFERENCE-SCALED PCA
            % =========================================================
            S.pcaReferenceValues = ...
                app.pcaReferenceValues;
            
            S.pcaReferenceScaledX = ...
                app.pcaReferenceScaledX;
            
            S.pcaReferenceDefinition = ...
                app.pcaReferenceDefinition;
            
            S.pcaReferenceFeatureType = ...
                app.pcaReferenceFeatureType;
            
            
            % =========================================================
            % CLASSIFICATION
            % =========================================================
            S.classMethod = ...
                app.classMethod;
            
            S.classX = ...
                app.classX;
            
            S.classWn = ...
                app.classWn;
            
            S.classGroupLabels = ...
                app.classGroupLabels;
            
            S.classGroupNames = ...
                app.classGroupNames;
            
            S.classSpectrumNames = ...
                app.classSpectrumNames;
            
            S.classNormMethod = ...
                app.classNormMethod;
            
            S.classScores = ...
                app.classScores;
            
            S.classLoadings = ...
                app.classLoadings;
            
            S.classExplained = ...
                app.classExplained;
            
            S.classComponentNames = ...
                app.classComponentNames;
            
            S.classPredictedLabels = ...
                app.classPredictedLabels;
            
            S.classAccuracy = ...
                app.classAccuracy;
            
            S.classConfusion = ...
                app.classConfusion;
            
            % =========================================================
            % SPATIAL DISTRIBUTION
            %
            % Store only metadata / recipes.
            % Never store six map matrices or cube cache here.
            % =========================================================
            S.spatialInitialized = ...
                app.spatialInitialized;
            
            S.spatialDatasets = ...
                app.spatialDatasets;
            
            S.spatialMapSource = ...
                app.spatialMapSource;
            
            S.spatialMapType = ...
                app.spatialMapType;
            
            S.spatialColorMap = ...
                app.spatialColorMap;
            
            S.spatialColorScale = ...
                app.spatialColorScale;
            
            S.spatialReferenceBand = ...
                app.spatialReferenceBand;
            
            S.spatialRecipeHistory = ...
                app.spatialRecipeHistory;
            
            S.spatialMetadataVersion = ...
                app.spatialMetadataVersion;
            
            
            % Panel state without large map matrices.
            spatialSavedPanels = ...
                app.spatialPanels;
            
            
            for spatialPanelIndex = 1:numel(spatialSavedPanels)
                
                spatialSavedPanels(spatialPanelIndex).MapData = [];
                spatialSavedPanels(spatialPanelIndex).ReferenceData = [];
                
            end
            
            
            S.spatialPanels = ...
                spatialSavedPanels;
            % =========================================================
            % AUDIT
            % =========================================================
            S.ActivityHistory = ...
                app.ActivityHistory;
            
            S.MetricHistory = ...
                app.MetricHistory;
            
            S.ConfirmedStages = ...
                app.ConfirmedStages;
            
            S.PendingPreview = ...
                app.PendingPreview;
            
            S.CurrentStage = ...
                app.CurrentStage;
            
            S.CurrentDataStatus = ...
                app.CurrentDataStatus;
            
            S.OneClickResult = ...
                app.OneClickResult;
            
            S.OneClickMode = ...
                app.OneClickMode;
            
            
            % =========================================================
            % SAVE
            % =========================================================
            filePath = fullfile( ...
                sessionFolder, ...
                'single_home_session.mat');
            
            save( ...
                filePath, ...
                'S', ...
                '-v7.3');
            
            
            app.singleLastSessionFile = ...
                string(filePath);
            
            
            % =========================================================
            % AUXILIARY AUDIT EXPORT
            % =========================================================
            app.saveSingleQCReport(false);
            
            app.saveActivityHistory(false);
            
            try
                app.saveSpatialMetadata(false);
            catch
                warning( ...
                    'RamanCube:SpatialMetadataSaveFailed', ...
                    'Could not save spatial metadata.');
            end
            
            % =========================================================
            % MESSAGE
            % =========================================================
            if showMessage
                
                msgbox( ...
                    sprintf([ ...
                    'Session saved successfully.\n\n' ...
                    'Saved file:\n%s\n\n' ...
                    'Ratio, PCA, reference-scaling data and analysis state were preserved.'], ...
                    filePath), ...
                    'Session saved');
                
            end
            
        end
        
        
        function ok = loadSingleSessionFromFile(app, filePath, showMessage)
            
            if nargin < 3
                showMessage = true;
            end
            
            ok = false;
            
            filePath = string(filePath);
            
            if strlength(filePath) == 0
                return
            end
            
            if ~isfile(filePath)
                uialert(app.UIFigure, ...
                    sprintf('Session file does not exist:\n\n%s', filePath), ...
                    'Session not found');
                return
            end
            
            
            try
                
                loaded = load(char(filePath), 'S');
                
                if ~isfield(loaded, 'S')
                    
                    uialert(app.UIFigure, ...
                        'Selected file does not contain variable S.', ...
                        'Invalid session');
                    
                    return
                end
                
                
                S = loaded.S;
                
                
                % =====================================================
                % Apply saved properties
                % =====================================================
                fields = fieldnames(S);
                
                for k = 1:numel(fields)
                    
                    fn = fields{k};
                    
                    if isprop(app, fn)
                        
                        try
                            app.(fn) = S.(fn);
                        catch ME
                            warning( ...
                                'Could not restore property "%s": %s', ...
                                fn, ...
                                ME.message);
                        end
                        
                    end
                end
                
                % =========================================================
                % UPGRADE / NORMALIZE INTERPRETATION REGISTRY
                % =========================================================
                app.ensureInterpretationRegistrySchema();
                
                app.summaryDirty = true;
                
                % Upgrade old Optimize-scan metadata schema if necessary.
                app.ensureSpatialOptimizeHistorySchema();
                
                
                % =====================================================
                % Restore session state
                % =====================================================
                app.singleHomeLoaded = true;
                app.singleLastSessionFile = filePath;
                
                
                % =====================================================
                % Refresh UI
                % =====================================================
                if ~isempty(app.singleData) && ...
                        isstruct(app.singleData)
                    
                    app.plotSingleHomeSpectra();
                    
                end
                
                
                if ~isempty(app.singleQC) && ...
                        isstruct(app.singleQC)
                    
                    app.updateSingleQCDisplay();
                    
                end
                
                
                if ~isempty(app.compareGroups)
                    
                    app.updateGroupDataDropdown();
                    app.updateSpectralTypeAvailability();
                    
                end
                
                % =========================================================
                % SUMMARY UI
                % =========================================================
                
                try
                    
                    app.ensureInterpretationRegistrySchema();
                    
                    app.refreshSummaryComparisonDropdown();
                    
                    app.rebuildSummaryTree();
                    
                    app.plotSummaryOverview();
                    
                catch
                end
                
                
                app.TabGroup.SelectedTab = app.HomeTab;
                
                drawnow;
                
                
                % =========================================================
                % RESTORE RATIO ANALYZE UI
                % =========================================================
                
                try
                    
                    % Build current canonical peak tree from restored
                    % peakLibrary + spectralDef.
                    app.ratioPeakTable = ...
                        app.buildRatioPeakTable();
                    
                    app.populateRatioPeakTree();
                    
                    
                    if ~isempty(app.ratioScreeningAll)
                        
                        app.ratioVisibleRowMap = ...
                            (1:height(app.ratioScreeningAll))';
                        
                        app.refreshRatioScreeningTable();
                        
                        app.buildRatioSummaryTree();
                        
                        app.ratioAnalyzeReady = ...
                            true;
                        
                    else
                        
                        app.resetRatioSummaryTree();
                        
                    end
                    
                    
                    cla(app.UIAxes10,'reset');
                    
                    app.UIAxes10.Box = 'on';
                    
                    title( ...
                        app.UIAxes10, ...
                        'Select a ratio result');
                    
                catch ME
                    
                    warning( ...
                        'RamanCube:RatioRestoreUI', ...
                        'Could not restore Ratio UI: %s', ...
                        ME.message);
                    
                end
                
                
                % =====================================================
                % Audit
                % =====================================================
                app.logActivity( ...
                    "Session", ...
                    "Restore session", ...
                    "Session file", ...
                    "RESTORED", ...
                    filePath);
                
                if showMessage
                    
                    msgbox( ...
                        sprintf( ...
                        'Previous Single spectra session restored:\n%s', ...
                        filePath), ...
                        'Restore session');
                    
                end
                
                
                ok = true;
                
                
            catch ME
                
                uialert(app.UIFigure, ...
                    sprintf([ ...
                    'Cannot restore session.\n\n' ...
                    '%s'], ...
                    ME.message), ...
                    'Restore session error');
                
            end
        end
        
        
        function ok = restoreSingleHomeSession(app)
            
            ok = false;
            
            startFolder = app.singleOutputRoot;
            
            if ~isfolder(startFolder)
                startFolder = pwd;
            end
            
            
            [file, path] = uigetfile( ...
                {'*.mat', 'MAT session (*.mat)'}, ...
                'Select previous single_home_session.mat', ...
                startFolder);
            
            
            if isequal(file, 0)
                return
            end
            
            
            filePath = fullfile(path, file);
            
            ok = app.loadSingleSessionFromFile( ...
                string(filePath), ...
                true);
        end
        
        

        function interpolateSingleWNToReference(app)
            
            if isempty(app.singleData) || ~isfield(app.singleData, 'WnCell') || ~isfield(app.singleData, 'SpectraCell')
                uialert(app.UIFigure, ...
                    'No single spectra data found.', ...
                    'WN correction');
                return
            end
            
            nSpectra = numel(app.singleData.WnCell);
            
            if nSpectra == 0
                uialert(app.UIFigure, ...
                    'No spectra are available for WN correction.', ...
                    'WN correction');
                return
            end
            
            lengths = cellfun(@numel, app.singleData.WnCell);
            refLength = mode(lengths);
            refIdx = find(lengths == refLength, 1, 'first');
            
            if isempty(refIdx)
                refIdx = 1;
            end
            
            refWn = app.singleData.WnCell{refIdx}(:);
            
            if numel(refWn) < 5 || any(~isfinite(refWn))
                uialert(app.UIFigure, ...
                    'Reference WN grid is invalid.', ...
                    'WN correction');
                return
            end
            
            choice = questdlg( ...
                sprintf([ ...
                'WN INTERPOLATION CONFIRMATION\n\n' ...
                'Reference spectrum index: %d\n' ...
                'Reference grid:\n' ...
                '- Points: %d\n' ...
                '- Range: %.2f - %.2f cm^{-1}\n\n' ...
                'This correction will:\n' ...
                '- Interpolate all spectra to this reference WN grid.\n' ...
                '- Preserve original spectra before correction inside app.singleData.\n' ...
                '- Update Home QC, Preprocess, and Analysis data after correction.\n' ...
                '- Save corrected data and correction log into Corrected_QC.\n\n' ...
                'Scientific caution:\n' ...
                'Use this only for small WN axis inconsistencies. If files come from different instruments, different spectral windows, or incompatible acquisition settings, reload/reorganize data instead.\n\n' ...
                'Continue?'], ...
                refIdx, numel(refWn), min(refWn), max(refWn)), ...
                'WN interpolation correction', ...
                'Interpolate', ...
                'Cancel', ...
                'Cancel');
            
            if ~strcmp(choice, 'Interpolate')
                return
            end
            
            correctedSpectraCell = cell(nSpectra,1);
            correctedWnCell = cell(nSpectra,1);
            
            badCount = 0;
            
            for k = 1:nSpectra
                
                wnK = app.singleData.WnCell{k}(:);
                yK = app.singleData.SpectraCell{k}(:);
                
                valid = isfinite(wnK) & isfinite(yK);
                wnK = wnK(valid);
                yK = yK(valid);
                
                [wnUnique, ia] = unique(wnK, 'stable');
                yUnique = yK(ia);
                
                if numel(wnUnique) < 5
                    correctedSpectraCell{k} = NaN(size(refWn));
                    correctedWnCell{k} = refWn;
                    badCount = badCount + 1;
                    continue
                end
                
                % interp1 needs a monotonic X axis.
                [wnSorted, sortIdx] = sort(wnUnique, 'ascend');
                ySorted = yUnique(sortIdx);
                
                correctedSpectraCell{k} = interp1( ...
                    wnSorted, ...
                    ySorted, ...
                    refWn, ...
                    'linear', ...
                    'extrap');
                
                correctedWnCell{k} = refWn;
            end
            
            app.singleData.OriginalWnCellBeforeCorrection = app.singleData.WnCell;
            app.singleData.OriginalSpectraCellBeforeCorrection = app.singleData.SpectraCell;
            
            app.singleData.WnCell = correctedWnCell;
            app.singleData.SpectraCell = correctedSpectraCell;
            
            app.singleData.CorrectionLog = sprintf( ...
                'WN interpolated to common reference grid on %s. Reference index: %d. Bad spectra: %d.', ...
                char(datetime('now')), refIdx, badCount);
            
            app.singleHomeCorrected = true;
            
            app.refreshSingleQCStateAfterCorrection( ...
                "WN interpolation");
            
            corrFolder = fullfile(app.singleOutputSessionFolder, '01_QC');
            if ~isfolder(corrFolder)
                mkdir(corrFolder);
            end
            
            correctedWn = app.wn;
            correctedSpectra = app.spectra;
            correctedNames = app.names;
            correctionLog = app.singleData.CorrectionLog;
            
            save(fullfile(corrFolder, 'corrected_wn_interpolated_data.mat'), ...
                'correctedWn', ...
                'correctedSpectra', ...
                'correctedNames', ...
                'correctionLog', ...
                '-v7.3');
            
            fid = fopen(fullfile(corrFolder, 'correction_log.txt'), 'a');
            
            if fid ~= -1
                fprintf(fid, '%s\n', char(correctionLog));
                fclose(fid);
            end
            
            msgbox('WN correction completed. QC has been updated.', ...
                'Correction completed');
        end
        
        
        function editSingleGroupLabels(app)
            
            if isempty(app.singleGroupTable)
                
                msgbox( ...
                    'No group table is available.', ...
                    'Metadata correction');
                
                return
                
            end
            
            
            % =====================================================
            % CURRENT GROUP INFORMATION
            % =====================================================
            
            oldLabels = string( ...
                app.singleGroupTable.GroupID(:));
            
            
            if ismember( ...
                    'SourceFolderName', ...
                    app.singleGroupTable.Properties.VariableNames)
                
                sourceFolderNames = string( ...
                    app.singleGroupTable.SourceFolderName(:));
                
            else
                
                sourceFolderNames = ...
                    strings(height(app.singleGroupTable),1);
                
                for k = 1:height(app.singleGroupTable)
                    
                    [~, folderName] = fileparts( ...
                        app.singleGroupTable.InputFolder(k));
                    
                    sourceFolderNames(k) = ...
                        string(folderName);
                    
                end
                
            end
            
            
            % =====================================================
            % INPUT DIALOG
            % =====================================================
            
            prompt = ...
                cell(numel(oldLabels),1);
            
            defaults = ...
                cell(numel(oldLabels),1);
            
            
            for k = 1:numel(oldLabels)
                
                prompt{k} = sprintf( ...
                    'App label for physical folder "%s":', ...
                    sourceFolderNames(k));
                
                defaults{k} = ...
                    char(oldLabels(k));
                
            end
            
            
            answ = inputdlg( ...
                prompt, ...
                'Correct group folder labels', ...
                [1 75], ...
                defaults);
            
            
            if isempty(answ)
                return
            end
            
            
            newLabels = ...
                strings(numel(answ),1);
            
            
            % =====================================================
            % VALIDATE
            % =====================================================
            
            for k = 1:numel(answ)
                
                candidate = ...
                    string(strtrim(answ{k}));
                
                if ~app.isStrictGroupLabel(candidate)
                    
                    uialert( ...
                        app.UIFigure, ...
                        sprintf([ ...
                        'Invalid app label:\n\n%s\n\n' ...
                        'Reason: %s\n\n' ...
                        'The physical folder will not be renamed.'], ...
                        candidate, ...
                        app.getStrictLabelIssue(candidate)), ...
                        'Invalid group label');
                    
                    return
                    
                end
                
                newLabels(k) = ...
                    candidate;
                
            end
            
            
            if numel(unique(newLabels)) < ...
                    numel(newLabels)
                
                uialert( ...
                    app.UIFigure, ...
                    'Each group must have a unique app label.', ...
                    'Duplicate group labels');
                
                return
                
            end
            
            
            % =====================================================
            % SAVE PERSISTENT RULES
            % =====================================================
            
            app.writeGroupLabelRules( ...
                sourceFolderNames, ...
                newLabels);
            
            
            % =====================================================
            % UPDATE GROUP-LEVEL TABLE
            % =====================================================
            
            app.singleGroupTable.DisplayName = ...
                newLabels;
            
            app.singleGroupTable.GroupID = ...
                newLabels;
            
            
            % =====================================================
            % UPDATE SPECTRUM-LEVEL METADATA
            % =====================================================
            
            for k = 1:numel(oldLabels)
                
                oldID = oldLabels(k);
                newID = newLabels(k);
                
                mask = ...
                    string(app.singleData.GroupIDs) == ...
                    oldID;
                
                app.singleData.GroupIDs(mask) = ...
                    newID;
                
                app.singleData.GroupLabels(mask) = ...
                    newID;
                
                
                if ~isempty(app.singleFileTable)
                    
                    maskFile = ...
                        string(app.singleFileTable.GroupID) == ...
                        oldID;
                    
                    app.singleFileTable.GroupID(maskFile) = ...
                        newID;
                    
                    app.singleFileTable.GroupLabel(maskFile) = ...
                        newID;
                    
                end
                
            end
            
            
            % =====================================================
            % UPDATE ACTIVE GROUP MEMORY
            % =====================================================
            
            oldActive = string( ...
                app.singleLastSelectedGroups(:));
            
            
            for k = 1:numel(oldLabels)
                
                oldActive( ...
                    oldActive == oldLabels(k)) = ...
                    newLabels(k);
                
            end
            
            
            app.singleLastSelectedGroups = ...
                oldActive;
            
            
            % =====================================================
            % LOG
            % =====================================================
            
            app.singleData.MetadataCorrectionLog = ...
                "Group label rules updated on " + ...
                string(datetime('now'));
            
            
            % =====================================================
            % FULL REFRESH
            % =====================================================
            
            app.singleQC = ...
                app.runSingleInitialQC();
            
            app.promoteSingleHomeDataToWorkspace();
            
            app.buildGroupsFromSingleHome();
            
            app.saveSingleQCReport(false);
            
            
            % Important:
            % always leave correction mode and return to Summary.
            app.restoreTreeToGroupSelection();
            
            
            app.logActivity( ...
                "Review data", ...
                "Correct group labels", ...
                "Persistent label rules", ...
                string(app.singleQC.Status), ...
                sprintf( ...
                "%d group label rule(s) updated.", ...
                numel(newLabels)));
            
            
            msgbox( ...
                sprintf([ ...
                'Group labels were updated.\n\n' ...
                'Original folders were not renamed.\n' ...
                'Persistent rules were saved to:\n%s'], ...
                fullfile( ...
                app.singleInputFolder, ...
                'group_label_rules.txt')), ...
                'Metadata correction completed');
            
        end
        
        
        % Function 1
        function [wn, spectra, filenames] = loadSpectra(app, folderPath)
            files = dir(fullfile(folderPath, '*.txt'));
            nFiles = length(files);
            spectra = [];
            filenames = strings(nFiles,1);
            for k = 1:nFiles
                filePath = fullfile(folderPath, files(k).name);
                data = app.readRamanTxtFlexible(filePath);
                if k == 1
                    wn = data(:,1);
                    spectra = zeros(length(wn), nFiles);
                end
                spectra(:,k) = data(:,2);
                filenames(k) = files(k).name;
            end
            app.EditField.Value = sprintf( ...
                'Spectra: %d ; Wavenumber: %.2f - %.2f cm^{-1}', ...
                nFiles, min(wn), max(wn));
        end
        
        % Function 2
        function [wn, cube] = loadMappedSpectra(app, folderPath)
            xFile = fullfile(folderPath, '1 Export File (X-Axis).txt');
            yFile = fullfile(folderPath, '1 Export File (Y-Axis).txt');
            hFile = fullfile(folderPath, '1 Export File (Header).txt');
            wn = load(xFile);
            y  = load(yFile);
            header = readlines(hFile);
            sizeX = sscanf(header(5), 'SizeX = %d');
            sizeY = sscanf(header(6), 'SizeY = %d');
            nWn = numel(wn);
            expectedLength = sizeX * sizeY * nWn;
            if numel(y) ~= expectedLength
                error('Y-Axis length mismatch. Expected %d values, got %d.', ...
                    expectedLength, numel(y));
            end
            cube = reshape(y, [nWn, sizeX, sizeY]);
            cube = permute(cube, [3 2 1]);
            app.EditField.Value = sprintf( ...
                'Cube: %d × %d × %d ; Wavenumber: %.2f - %.2f cm^{-1}', ...
                sizeY, sizeX, nWn, min(wn), max(wn));
        end
        
        % Function 3:
        
        function extractBandUI(app)
            if app.cosmicRemoved
                suffix = ' (cosmic removed)';
            else
                suffix = '';
            end
            choice = questdlg( ...
                'Select extraction mode:', ...
                'Extract 2D Band', ...
                'Single wavenumber', ...
                'Wavenumber range', ...
                'Cancel', ...
                'Single wavenumber');
            if isempty(choice) || strcmp(choice, 'Cancel')
                return;
            end
            switch choice
                case 'Single wavenumber'
                    answer = inputdlg( ...
                        {'Enter wavenumber:'}, ...
                        'Single Wavenumber Extraction', ...
                        [1 45], ...
                        {num2str(app.wn(round(end/2)))});
                    
                    if isempty(answer)
                        return;
                    end
                    targetWn = str2double(answer{1});
                    
                    if isnan(targetWn)
                        uialert(app.UIFigure, ...
                            'Invalid wavenumber value.', ...
                            'Input Error');
                        return;
                    end
                    [~, idx] = min(abs(app.wn - targetWn));
                    app.lastBandMode = "single";
                    app.lastBandWn = app.wn(idx);
                    app.lastBandLow = NaN;
                    app.lastBandHigh = NaN;
                    band2D = app.cube(:,:,idx);
                    img = imagesc(app.UIAxes, band2D);
                    if isprop(img, 'Interpolation')
                        img.Interpolation = 'nearest';
                    end
                    axis(app.UIAxes, 'image');
                    axis(app.UIAxes, 'tight');
                    app.UIAxes.XTickMode = 'auto';
                    app.UIAxes.YTickMode = 'auto';
                    xlabel(app.UIAxes, 'X pixel');
                    ylabel(app.UIAxes, 'Y pixel');
                    axis(app.UIAxes, 'image');
                    colorbar(app.UIAxes);
                    title(app.UIAxes, ...
                        sprintf('Band at %.2f cm^{-1}', app.wn(idx)));
                case 'Wavenumber range'
                    answer = inputdlg( ...
                        {'Start wavenumber:', 'End wavenumber:'}, ...
                        'Wavenumber Range Extraction', ...
                        [1 45], ...
                        {num2str(min(app.wn)), ...
                        num2str(max(app.wn))});
                    if isempty(answer)
                        return;
                    end
                    wn1 = str2double(answer{1});
                    wn2 = str2double(answer{2});
                    if isnan(wn1) || isnan(wn2)
                        uialert(app.UIFigure, ...
                            'Invalid wavenumber range.', ...
                            'Input Error');
                        return;
                    end
                    wnLow  = min(wn1, wn2);
                    wnHigh = max(wn1, wn2);
                    app.lastBandMode = "range";
                    app.lastBandWn = NaN;
                    app.lastBandLow = wnLow;
                    app.lastBandHigh = wnHigh;
                    idx = app.wn >= wnLow & app.wn <= wnHigh;
                    if ~any(idx)
                        uialert(app.UIFigure, ...
                            'No wavenumber found in this range.', ...
                            'Input Error');
                        return;
                    end
                    band2D = mean(app.cube(:,:,idx), 3);
                    img = imagesc(app.UIAxes, band2D);
                    if isprop(img, 'Interpolation')
                        img.Interpolation = 'nearest';
                    end
                    axis(app.UIAxes, 'image');
                    axis(app.UIAxes, 'tight');
                    app.UIAxes.XTickMode = 'auto';
                    app.UIAxes.YTickMode = 'auto';
                    xlabel(app.UIAxes, 'X pixel');
                    ylabel(app.UIAxes, 'Y pixel');
                    axis(app.UIAxes, 'image');
                    colorbar(app.UIAxes);
                    title(app.UIAxes, ...
                        sprintf('Band %.2f - %.2f cm^{-1}%s', ...
                        wnLow, wnHigh, suffix));
            end
            xlabel(app.UIAxes, 'X pixel');
            ylabel(app.UIAxes, 'Y pixel');
        end
        
        % Function 6:
        function spectraMatrix = cubeToSpectraMatrix(app, cubeData)
            ny = size(cubeData, 1);
            nx = size(cubeData, 2);
            nWn = size(cubeData, 3);
            spectraMatrix = reshape( ...
                permute(cubeData, [3 2 1]), ...
                nWn, nx * ny);
        end
        
        
        % Function 12:
        function setupManageDataFolder(app)
            candidates = { ...
                fullfile('D:\', 'Raman general', 'data', 'data from app'), ...
                fullfile('E:\', 'Raman general', 'data', 'data from app'), ...
                fullfile('C:\', 'Raman general', 'data', 'data from app')};
            app.manageDataFolder = "";
            for k = 1:numel(candidates)
                driveRoot = extractBefore(candidates{k}, 4); % 'D:\', 'E:\', ...
                if exist(driveRoot, 'dir')
                    app.manageDataFolder = candidates{k};
                    break
                end
            end
            if app.manageDataFolder == ""
                appFolder = fileparts(mfilename('fullpath'));
                app.manageDataFolder = fullfile(appFolder, 'data', 'data from app');
            end
            if ~exist(app.manageDataFolder, 'dir')
                mkdir(app.manageDataFolder);
            end
        end
        
        % Function 13:
        function name = sanitizeFolderName(app, name)
            name = strtrim(string(name));
            invalidChars = ["\", "/", ":", "*", "?", """", "<", ">", "|"];
            for k = 1:numel(invalidChars)
                name = replace(name, invalidChars(k), "_");
            end
        end
        
        % Function 14:
        function ok = askLoadModeAndProjectFolder(app)
            ok = false;
            choice = questdlg( ...
                'How would you like to load the data?', ...
                'Load option', ...
                'Quick test - no backup', ...
                'Backup data', ...
                'Cancel', ...
                'Quick test - no backup');
            if isempty(choice) || strcmp(choice, 'Cancel')
                return
            end
            switch choice
                case 'Quick test - no backup'
                    app.shouldSaveData = false;
                    app.projectName = "";
                    app.projectFolder = "";
                    ok = true;
                case 'Backup data'
                    app.shouldSaveData = true;
                    app.setupManageDataFolder();
                    while true
                        answer = inputdlg( ...
                            {'Enter backup folder name:'}, ...
                            'Create backup folder', ...
                            [1 60], ...
                            {'Raman_project'});
                        if isempty(answer)
                            app.shouldSaveData = false;
                            return
                        end
                        folderName = app.sanitizeFolderName(answer{1});
                        if folderName == ""
                            uialert(app.UIFigure, ...
                                'Folder name cannot be empty.', ...
                                'Invalid folder name');
                            continue
                        end
                        targetFolder = fullfile(app.manageDataFolder, folderName);
                        if exist(targetFolder, 'dir')
                            uialert(app.UIFigure, ...
                                'This folder name already exists. Please choose another name.', ...
                                'Duplicate folder name');
                            continue
                        end
                        mkdir(targetFolder);
                        app.projectName = folderName;
                        app.projectFolder = targetFolder;
                        ok = true;
                        return
                    end
            end
        end
        
        % Function 15:
        function appendHistory(app, stepText)
            stepText = string(stepText);
            if app.processHistory == ""
                app.processHistory = stepText;
            else
                app.processHistory = app.processHistory + " --> " + stepText;
            end
            app.updateInfoField();
        end
        
        % Function 16:
        function updateInfoField(app)
            if isempty(app.mode) || isempty(app.wn)
                app.EditField.Value = '';
                return
            end
            switch app.mode
                case 1
                    if isempty(app.spectra)
                        dataInfo = "Single spectra: no data";
                    else
                        dataInfo = sprintf( ...
                            'Single spectra: %d spectra; %d points/spectrum; WN %.2f - %.2f cm^{-1}', ...
                            size(app.spectra, 2), numel(app.wn), min(app.wn), max(app.wn));
                    end
                case 2
                    if isempty(app.cube)
                        dataInfo = "Scan spectra: no data";
                    else
                        dataInfo = sprintf( ...
                            'Scan cube: %d x %d pixels; %d WN points; WN %.2f - %.2f cm^{-1}', ...
                            size(app.cube, 1), size(app.cube, 2), size(app.cube, 3), ...
                            min(app.wn), max(app.wn));
                    end
                otherwise
                    dataInfo = "Unknown data mode";
            end
            if app.processHistory ~= ""
                app.EditField.Value = char(string(dataInfo) + " --> " + app.processHistory);
            else
                app.EditField.Value = char(dataInfo);
            end
        end
        
        % Function 18
        function resetCurrentSession(app)
            
            app.mode = [];
            app.wn = [];
            app.spectra = [];
            app.cube = [];
            app.rawSpectra = [];
            app.rawCube = [];
            
            app.projectName = "";
            app.projectFolder = "";
            app.shouldSaveData = false;
            app.processHistory = "";
            app.cosmicRemoved = false;
            
            app.singleHomeLoaded = false;
            app.singleHomeCorrected = false;
            app.singleData = struct();
            app.singleQC = struct();
            app.singleGroupTable = table();
            app.singleFileTable = table();
            app.singleOutputSessionFolder = "";
            app.singleSessionName = "";
            app.singleSessionID = "";
            app.singleLastSelectedGroups = strings(0,1);
            app.singleLastSessionFile = "";
            app.singleSNRThreshold = 10;
            app.singleSNRAccepted = false;
            app.singleSNRAcceptNote = "";
            app.singleWarningViewMode = "";
            
            app.analysisSource = "";
            app.analysisWn = [];
            app.analysisSpectra = [];
            app.analysisRawSpectra = [];
            app.analysisNames = strings(0,1);
            app.analysisHistory = "";
            
            app.baseGroups = struct('Name', {}, 'Indices', {}, 'Count', {});
            app.compareGroups = struct('Name', {}, 'BaseGroupNames', {}, 'Indices', {}, 'Count', {});
            % =========================================================
            % RESET INTEGRATED INTERPRETATION
            % =========================================================
            
            app.interpretationRegistry = ...
                app.emptyInterpretationRegistry();
            
            app.interpretSession = ...
                struct();
            
            app.ratioViewMode = ...
                "Analyze";
            
            app.ratioSelectedRow = ...
                NaN;
            
            app.summarySelectedEvidenceID = ...
                "";
            
            app.summaryDirty = ...
                true;
            
            cla(app.UIAxes, 'reset');
            title(app.UIAxes, 'Review figure');
            app.UIAxes.Box = 'on';
            
            cla(app.UIAxes8, 'reset');
            title(app.UIAxes8, 'Spectral review');
            xlabel(app.UIAxes8, 'Raman shift (cm^{-1})');
            ylabel(app.UIAxes8, 'Intensity (a.u.)');
            grid(app.UIAxes8, 'off');
            app.UIAxes8.Box = 'on';
            
            app.safeSetComponentValue('DatamodesDropDown', 'Value', '');
            app.safeSetComponentValue('ScantypeDropDown', 'Value', '');
            app.safeSetComponentValue('MapcolorDropDown', 'Value', '');
            app.safeSetComponentValue('ScantypeDropDown', 'Enable', 'off');
            app.safeSetComponentValue('MapcolorDropDown', 'Enable', 'off');
            
            app.EditField.Value = '';
            
            app.TextArea_2.Value = {'Overall:'};
            app.TextArea_3.Value = {'Metadata structure errors:'; ''};
            app.TextArea_4.Value = {'Wavenumber axis errors:'; ''};
            app.TextArea_5.Value = {'SNR errors:'; ''};
            
            app.CorrectQCdataButton.Enable = 'off';
            
            if isprop(app, 'DetailedwarningButton')
                app.DetailsButton.Enable = 'off';
            end
            
            app.GroupdataDropDown.Items = {''};
            app.GroupdataDropDown.Value = '';
            app.SpectraltypesDropDown.Items = {''};
            app.SpectraltypesDropDown.Value = '';
            
            % Safe cleanup for optional scan components if they exist in future versions.
            if isprop(app, 'UIAxes9') && isvalid(app.UIAxes9)
                cla(app.UIAxes9, 'reset');
            end
            
            if isprop(app, 'OptionsDropDown') && isvalid(app.OptionsDropDown)
                app.OptionsDropDown.Items = {''};
                app.OptionsDropDown.Value = '';
            end
            
            if isprop(app, 'OptionsDropDown_2') && isvalid(app.OptionsDropDown_2)
                app.OptionsDropDown_2.Items = {''};
                app.OptionsDropDown_2.Value = '';
            end
            
            if isprop(app, 'SavedfeaturesDropDown') && isvalid(app.SavedfeaturesDropDown)
                app.SavedfeaturesDropDown.Items = {''};
                app.SavedfeaturesDropDown.Value = '';
            end
            
            if isprop(app, 'MapcolorDropDown_2') && isvalid(app.MapcolorDropDown_2)
                app.MapcolorDropDown_2.Value = '';
            end
            
            
            % =========================================================
            % RESET RATIO ANALYZE WORKSPACE
            % =========================================================
            
            app.ratioPeakTable = ...
                table();
            
            app.ratioFeatureMatrix = [];
            
            app.ratioAnalysisGroupLabels = ...
                strings(0,1);
            
            app.ratioAnalysisGroupNames = ...
                strings(0,1);
            
            app.ratioVisibleRowMap = [];
            
            app.ratioSelectedResultIndex = ...
                NaN;
            
            app.ratioBoxYLim = [];
            
            app.ratioFeatureType = ...
                "Area";
            
            app.ratioTestType = ...
                "Auto";
            
            app.ratioFormulaVariables = ...
                strings(0,1);
            
            app.ratioFormulaPeakIDs = [];
            
            app.ratioAnalyzeReady = ...
                false;
            
            app.ratioScreeningSelectedPeaks = ...
                table();
            
            app.ratioScreeningAll = ...
                table();
            
            app.ratioScreeningTop = ...
                table();
            
            app.ratioScreeningValues = [];
            
            app.ratioScreeningGroupLabels = ...
                strings(0,1);
            
            app.ratioScreeningGroupNames = ...
                strings(0,1);
            
            app.ratioScreeningSpectrumNames = ...
                strings(0,1);
            
            % =========================================================
            % RESET SPATIAL DISTRIBUTION
            % =========================================================
            app.spatialInitialized = false;
            app.spatialOptimizeHistory = struct( ...
                'OverlayID', {}, ...
                'PanelID', {}, ...
                'DatasetID', {}, ...
                'DatasetName', {}, ...
                'MapType', {}, ...
                'SelectionMode', {}, ...
                'PercentileLow', {}, ...
                'PercentileHigh', {}, ...
                'ValueThresholdLow', {}, ...
                'ValueThresholdHigh', {}, ...
                'Opacity', {}, ...
                'CreatedAt', {}, ...
                'User', {});
            
            app.spatialDatasets = struct( ...
                'ID', {}, ...
                'DisplayName', {}, ...
                'GroupName', {}, ...
                'SourceScanName', {}, ...
                'RawCubePath', {}, ...
                'ProcessedCubePath', {}, ...
                'WavenumberPath', {}, ...
                'MetadataPath', {}, ...
                'SourceSessionFolder', {}, ...
                'SourceStage', {}, ...
                'Ny', {}, ...
                'Nx', {}, ...
                'NWn', {}, ...
                'WnMin', {}, ...
                'WnMax', {}, ...
                'CreatedAt', {});
            
            app.spatialPanels = struct([]);
            
            app.spatialCurrentRecipe = struct();
            
            app.spatialRecipeHistory = struct( ...
                'RecipeID', {}, ...
                'MapType', {}, ...
                'MapSource', {}, ...
                'Parameters', {}, ...
                'ColorMap', {}, ...
                'ColorScale', {}, ...
                'CreatedAt', {}, ...
                'User', {});
            
            app.spatialCache = struct( ...
                'DatasetID', "", ...
                'RawCube', [], ...
                'ProcessedCube', [], ...
                'Wn', [], ...
                'ValidMask', [], ...
                'CellMask', [], ...
                'BackgroundMask', [], ...
                'UncertainMask', [], ...
                'AnalysisMask', [], ...
                'MaskSource', "", ...
                'LoadedAt', "");
            
            try
                app.initializeSpatialDistribution();
            catch
            end
        end
        
        % Function 19:
        function refreshMainAxesAfterChild(app)
            cla(app.UIAxes, 'reset');
            app.UIAxes.Box = 'on';
            app.UIAxes.XTickMode = 'auto';
            app.UIAxes.YTickMode = 'auto';
            app.UIAxes.XColor = [0 0 0];
            app.UIAxes.YColor = [0 0 0];
            if app.cosmicRemoved
                suffix = ' (cosmic removed)';
            else
                suffix = '';
            end
            switch app.mode
                case 1
                    plot(app.UIAxes, app.wn, app.spectra, 'LineWidth', 0.8);
                    xlabel(app.UIAxes, 'Wavenumber (cm^{-1})');
                    ylabel(app.UIAxes, 'Intensity');
                    xlim(app.UIAxes,app.getDisplayXLim());
                    title(app.UIAxes, ['Checked single spectra' suffix]);
                    grid(app.UIAxes, 'off');
                case 2
                    
                    switch app.lastBandMode
                        
                        case "single"
                            [~, idx] = min(abs(app.wn - app.lastBandWn));
                            band2D = app.cube(:,:,idx);
                            titleText = sprintf('Checked scan at %.2f cm^{-1}%s', ...
                                app.wn(idx), suffix);
                            
                        case "range"
                            idx = app.wn >= app.lastBandLow & app.wn <= app.lastBandHigh;
                            
                            if any(idx)
                                band2D = mean(app.cube(:,:,idx), 3);
                                titleText = sprintf('Checked scan %.2f - %.2f cm^{-1}%s', ...
                                    app.lastBandLow, app.lastBandHigh, suffix);
                            else
                                midIdx = round(numel(app.wn)/2);
                                band2D = app.cube(:,:,midIdx);
                                titleText = sprintf('Checked scan preview at %.2f cm^{-1}%s', ...
                                    app.wn(midIdx), suffix);
                            end
                        otherwise
                            midIdx = round(numel(app.wn)/2);
                            band2D = app.cube(:,:,midIdx);
                            titleText = sprintf('Checked scan preview at %.2f cm^{-1}%s', ...
                                app.wn(midIdx), suffix);
                    end
                    img = imagesc(app.UIAxes, band2D);
                    if isprop(img, 'Interpolation')
                        img.Interpolation = 'nearest';
                    end
                    axis(app.UIAxes, 'normal');
                    app.UIAxes.DataAspectRatioMode = 'auto';
                    app.UIAxes.PlotBoxAspectRatioMode = 'auto';
                    vmin = min(band2D(:), [], 'omitnan');
                    vmax = max(band2D(:), [], 'omitnan');
                    if isfinite(vmin) && isfinite(vmax) && vmin < vmax
                        caxis(app.UIAxes, [vmin vmax]);
                    end
                    switch lower(app.MapcolorDropDown.Value)
                        case 'jet'
                            colormap(app.UIAxes, jet);
                        case 'hot'
                            colormap(app.UIAxes, hot);
                        case 'turbo'
                            colormap(app.UIAxes, turbo);
                        case 'parula'
                            colormap(app.UIAxes, parula);
                        case 'bone'
                            colormap(app.UIAxes, bone);
                        case 'gray'
                            colormap(app.UIAxes, gray);
                        otherwise
                            colormap(app.UIAxes, jet);
                    end
                    colorbar(app.UIAxes);
                    xlabel(app.UIAxes, 'X pixel');
                    ylabel(app.UIAxes, 'Y pixel');
                    title(app.UIAxes, titleText);
                    app.UIAxes.XTickMode = 'auto';
                    app.UIAxes.YTickMode = 'auto';
                    app.UIAxes.XColor = [0 0 0];
                    app.UIAxes.YColor = [0 0 0];
                    app.UIAxes.Box = 'on';
                    drawnow;
            end
        end
        
        % Function 20:
        function M = getCurrentFinalSpectraMatrix(app)
            M = [];
            if isempty(app.mode) || isempty(app.wn)
                return
            end
            switch app.mode
                case 1
                    M = app.spectra;
                case 2
                    M = app.cubeToSpectraMatrix(app.cube);
            end
        end


        function clearBandCheckDisplay(app)
            
            % Delete only Band check graphics.
            try
                
                oldObjects = ...
                    findall( ...
                    app.UIAxes2, ...
                    'Tag', ...
                    'BandCheck');
                
                delete(oldObjects);
                
            catch
            end
            
        end
        
        % Function 21:
        function M = getRawMeanAnalysisMatrix(app)
            M = [];
            if isempty(app.mode) || isempty(app.wn)
                return
            end
            switch app.mode
                case 1
                    if ~isempty(app.rawSpectra)
                        M = app.rawSpectra;
                    else
                        M = app.spectra;
                    end
                case 2
                    if ~isempty(app.rawCube)
                        M = app.cubeToSpectraMatrix(app.rawCube);
                    else
                        M = app.cubeToSpectraMatrix(app.cube);
                    end
            end
        end
        
        % Function 22:
        function names = makeDefaultSpectrumNames(app, n)
            names = strings(n,1);
            for k = 1:n
                names(k) = "spec" + k;
            end
        end


        function applyBandCheck(app, showAlert)
            
            if nargin < 2
                showAlert = true;
            end
            
            % =========================================================
            % CLEAR OLD BAND MARKERS
            % =========================================================
            app.clearBandCheckDisplay();
            
            % =========================================================
            % REQUIRE A DISPLAYED SPECTRUM
            % =========================================================
            if isempty(app.plottedWn)
                
                if showAlert
                    
                    uialert( ...
                        app.UIFigure, ...
                        ['Display spectra first, then use Band check.'], ...
                        'Band check');
                    
                end
                
                return
            end
            
            rows = ...
                app.getSelectedBandRows();
            
            if isempty(rows)
                
                app.updateBandCheckText([]);
                
                return
            end
            
            cols = ...
                app.getBandCheckLibraryColumns();
            
            if isempty(cols.Theory)
                return
            end
            
            T = ...
                app.peakLibrary;
            
            % =========================================================
            % CURRENT Y LIMIT
            % =========================================================
            yl = ...
                ylim(app.UIAxes2);
            
            y1 = yl(1);
            y2 = yl(2);
            
            hold(app.UIAxes2, 'on');
            
            observed = ...
                NaN(height(T),1);
            
            if isfield(app.spectralDef, 'peakObservedWN')
                
                candidate = ...
                    double(app.spectralDef.peakObservedWN(:));
                
                if numel(candidate) == height(T)
                    observed = candidate;
                end
                
            end
            
            for i = 1:numel(rows)
                
                r = rows(i);
                
                theoryText = ...
                    string(T{r,cols.Theory});
                
                [~, lowWN, highWN] = ...
                    app.parseBandLibraryRange( ...
                    theoryText);
                
                if ~isfinite(lowWN) || ...
                        ~isfinite(highWN)
                    
                    continue
                end
                
                % =====================================================
                % Ignore bands outside current plotted WN domain
                % =====================================================
                if highWN < min(app.plottedWn) || ...
                        lowWN > max(app.plottedWn)
                    
                    continue
                end
                
                lowWN = ...
                    max(lowWN, min(app.plottedWn));
                
                highWN = ...
                    min(highWN, max(app.plottedWn));
                
                % =====================================================
                % SHADED THEORETICAL BAND
                % =====================================================
                patch( ...
                    app.UIAxes2, ...
                    [lowWN highWN highWN lowWN], ...
                    [y1 y1 y2 y2], ...
                    [0.85 0.85 0.85], ...
                    'FaceAlpha', ...
                    0.18, ...
                    'EdgeColor', ...
                    'none', ...
                    'HandleVisibility', ...
                    'off', ...
                    'HitTest', ...
                    'off', ...
                    'Tag', ...
                    'BandCheck');
                
                % =====================================================
                % OBSERVED PEAK
                % =====================================================
                if isfinite(observed(r)) && ...
                        observed(r) >= min(app.plottedWn) && ...
                        observed(r) <= max(app.plottedWn)
                    
                    xline( ...
                        app.UIAxes2, ...
                        observed(r), ...
                        '--', ...
                        'LineWidth', ...
                        1.1, ...
                        'HandleVisibility', ...
                        'off', ...
                        'Tag', ...
                        'BandCheck');
                    
                end
                
            end
            
            hold(app.UIAxes2, 'off');
            
            % Preserve existing Y range.
            ylim(app.UIAxes2, yl);
            
            app.updateBandCheckText(rows);
            
        end


        function updateBandCheckText(app, rows)
            
            if isempty(rows)
                
                app.TextArea_7.Value = ...
                    {'No peak selected.'};
                
                return
            end
            
            if isempty(app.peakLibrary)
                
                app.TextArea_7.Value = ...
                    {'Peak library is unavailable.'};
                
                return
            end
            
            cols = ...
                app.getBandCheckLibraryColumns();
            
            T = ...
                app.peakLibrary;
            
            n = ...
                height(T);
            
            observed = ...
                NaN(n,1);
            
            if isfield(app.spectralDef, 'peakObservedWN')
                
                candidate = ...
                    double(app.spectralDef.peakObservedWN(:));
                
                if numel(candidate) == n
                    observed = candidate;
                end
                
            end
            
            % =========================================================
            % ALL:
            % Keep text compact.
            % =========================================================
            if numel(rows) > 5
                
                lines = strings(0,1);
                
                lines(end+1) = ...
                    sprintf( ...
                    'All defined peaks (%d bands)', ...
                    numel(rows));
                
                lines(end+1) = "";
                
                for i = 1:numel(rows)
                    
                    r = rows(i);
                    
                    theory = ...
                        app.getPeakLibraryText( ...
                        T, ...
                        r, ...
                        cols.Theory);
                    
                    assignment = ...
                        app.getPeakLibraryText( ...
                        T, ...
                        r, ...
                        cols.Assignment);
                    
                    if isfinite(observed(r))
                        
                        lines(end+1) = sprintf( ...
                            '%s | Obs %.1f | %s', ...
                            theory, ...
                            observed(r), ...
                            assignment);
                        
                    else
                        
                        lines(end+1) = sprintf( ...
                            '%s | %s', ...
                            theory, ...
                            assignment);
                        
                    end
                    
                end
                
                app.TextArea_7.Value = ...
                    cellstr(lines);
                
                return
            end
            
            % =========================================================
            % ONE OR FEW PEAKS:
            % Full compact information.
            % =========================================================
            lines = strings(0,1);
            
            for i = 1:numel(rows)
                
                r = rows(i);
                
                theory = ...
                    app.getPeakLibraryText(T,r,cols.Theory);
                
                mode = ...
                    app.getPeakLibraryText(T,r,cols.Mode);
                
                bond = ...
                    app.getPeakLibraryText(T,r,cols.Bond);
                
                assignment = ...
                    app.getPeakLibraryText(T,r,cols.Assignment);
                
                association = ...
                    app.getPeakLibraryText(T,r,cols.Association);
                
                note = ...
                    app.getPeakLibraryText(T,r,cols.Note);
                
                reference = ...
                    app.getPeakLibraryText(T,r,cols.Reference);
                
                lines(end+1) = ...
                    "Band: " + theory + " cm⁻¹";
                
                if isfinite(observed(r))
                    
                    lines(end+1) = ...
                        sprintf( ...
                        'Observed: %.1f cm⁻¹', ...
                        observed(r));
                    
                end
                
                if mode ~= ""
                    lines(end+1) = "Mode: " + mode;
                end
                
                if bond ~= ""
                    lines(end+1) = "Bond/group: " + bond;
                end
                
                if assignment ~= ""
                    lines(end+1) = "Assignment: " + assignment;
                end
                
                if association ~= ""
                    lines(end+1) = "Association: " + association;
                end
                
                if note ~= ""
                    lines(end+1) = "Note: " + note;
                end
                
                if reference ~= ""
                    lines(end+1) = "Ref: " + reference;
                end
                
                if i < numel(rows)
                    lines(end+1) = "";
                end
                
            end
            
            app.TextArea_7.Value = ...
                cellstr(lines);
            
        end


        function txt = getPeakLibraryText(app, T, row, col)
            
            txt = "";
            
            if isempty(col) || ...
                    row < 1 || ...
                    row > height(T)
                
                return
            end
            
            try
                
                txt = ...
                    strtrim( ...
                    string(T{row,col}));
                
                if ismissing(txt)
                    txt = "";
                end
                
            catch
                
                txt = "";
                
            end
            
        end
        
        
        % Function 23:
        function [wn, spectra, names] = loadExternalTxtSpectraForAnalysis(app, folderPath)
            files = dir(fullfile(folderPath, '*.txt'));
            if isempty(files)
                error('No TXT files found in this folder.');
            end
            n = numel(files);
            wn = [];
            spectra = [];
            names = strings(n,1);
            for k = 1:n
                fp = fullfile(folderPath, files(k).name);
                data = readmatrix(fp);
                if size(data,2) < 2
                    error('File %s must contain at least 2 columns: wavenumber and intensity.', files(k).name);
                end
                if k == 1
                    wn = data(:,1);
                    spectra = zeros(numel(wn), n);
                else
                    if numel(data(:,1)) ~= numel(wn) || max(abs(data(:,1) - wn), [], 'omitnan') > 1e-6
                        error('Wavenumber axis mismatch in file: %s', files(k).name);
                    end
                end
                spectra(:,k) = data(:,2);
                [~, nm, ~] = fileparts(files(k).name);
                names(k) = string(nm);
            end
        end


        function rows = getSelectedBandRows(app)
            
            rows = [];
            
            if isempty(app.ListBox.Items)
                return
            end
            
            selected = ...
                string(app.ListBox.Value);
            
            if isempty(selected)
                return
            end
            
            rowMap = ...
                app.ListBox.UserData;
            
            if isempty(rowMap)
                return
            end
            
            % =========================================================
            % ALL
            % =========================================================
            if any(strcmpi(selected, "All"))
                
                rows = ...
                    rowMap(:);
                
                return
            end
            
            % =========================================================
            % INDIVIDUAL SELECTIONS
            % Item #1 = All
            % Remaining items map to rowMap.
            % =========================================================
            items = ...
                string(app.ListBox.Items);
            
            for i = 1:numel(selected)
                
                idxItem = ...
                    find( ...
                    strcmp(items, selected(i)), ...
                    1);
                
                if isempty(idxItem) || idxItem == 1
                    continue
                end
                
                mapIndex = ...
                    idxItem - 1;
                
                if mapIndex >= 1 && ...
                        mapIndex <= numel(rowMap)
                    
                    rows(end+1,1) = ...
                        rowMap(mapIndex);
                    
                end
                
            end
            
            rows = ...
                unique(rows, 'stable');
            
        end
        
        % Function 24:
        function groupName = extractGroupNameFromSpectrumName(app, nameText)
            nameText = string(nameText);
            [~, nameText, ~] = fileparts(nameText);
            parts = split(nameText, "_");
            parts = parts(strlength(parts) > 0);
            removeMask = false(size(parts));
            for k = 1:numel(parts)
                token = lower(strtrim(parts(k)));
                removeMask(k) = ~isempty(regexp(char(token), '^spec\d+$|^spectrum\d+$', 'once'));
            end
            parts(removeMask) = [];
            if isempty(parts)
                groupName = nameText;
            else
                groupName = strjoin(parts, "_");
            end
        end
        
        % Function 25:
        function autoDetectBaseGroups(app)
            names = app.analysisNames(:);
            n = numel(names);
            if n == 0
                app.baseGroups = struct('Name', {}, 'Indices', {}, 'Count', {});
                return
            end
            key = strings(n,1);
            for i = 1:n
                key(i) = app.extractGroupNameFromSpectrumName(names(i));
            end
            u = unique(key, 'stable');
            bg = struct('Name', {}, 'Indices', {}, 'Count', {});
            for g = 1:numel(u)
                idx = find(key == u(g));
                bg(g).Name = char(u(g));
                bg(g).Indices = idx(:)';
                bg(g).Count = numel(idx);
            end
            app.baseGroups = bg;
        end
        
        % Function 26:
        function updateGroupDataDropdown(app)
            if isempty(app.compareGroups)
                app.GroupdataDropDown.Items = {''};
                app.GroupdataDropDown.Value = '';
                return
            end
            n = numel(app.compareGroups);
            items = strings(0,1);
            values = strings(0,1);
            items(end+1) = "";
            values(end+1) = "";
            items(end+1) = "All groups";
            values(end+1) = "All groups";
            % Single groups
            for i = 1:n
                items(end+1) = "G" + i;
                values(end+1) = "G" + i;
            end
            % Pair and triple combinations
            for r = 2:min(3,n)
                C = nchoosek(1:n, r);
                for k = 1:size(C,1)
                    label = "G" + join(string(C(k,:)), "");
                    items(end+1) = label;
                    values(end+1) = label;
                end
            end
            app.GroupdataDropDown.Items = cellstr(items);
            app.GroupdataDropDown.Value = '';
        end
        
        % Function 27:
        function selected = parseGroupSelection(app, value)
            
            selected = [];
            
            value = ...
                strtrim(string(value));
            
            if value == ""
                return
            end
            
            nGroups = ...
                numel(app.compareGroups);
            
            % =========================================================
            % ALL GROUPS
            % =========================================================
            if strcmpi(value, "All groups")
                
                selected = ...
                    1:nGroups;
                
                return
            end
            
            % =========================================================
            % EXTRACT ALL G-NUMBERS
            %
            % Handles:
            % G1
            % G1 + G2
            % G1 vs G2
            % =========================================================
            tokens = regexp( ...
                char(value), ...
                'G(\d+)', ...
                'tokens');
            
            if isempty(tokens)
                return
            end
            
            selected = ...
                NaN(1,numel(tokens));
            
            for k = 1:numel(tokens)
                
                selected(k) = ...
                    str2double(tokens{k}{1});
                
            end
            
            selected = ...
                selected(isfinite(selected));
            
            selected = ...
                unique(selected, 'stable');
            
            if any(selected < 1) || ...
                    any(selected > nGroups)
                
                selected = [];
                
            end
            
        end
        
        % Function 28:
        function mask = getWnMaskFromFilter(app)
            wn = app.analysisWn(:);
            if isempty(wn)
                mask = [];
                return
            end
            txt = strtrim(string(app.WavenumberfilterEditField.Value));
            if txt == ""
                mask = true(size(wn));
                return
            end
            nums = regexp(char(txt), '[-+]?\d*\.?\d+', 'match');
            nums = str2double(nums);
            if numel(nums) < 2 || any(isnan(nums(1:2)))
                uialert(app.UIFigure, ...
                    'Enter wavenumber filter as: start end, for example: 800 1800', ...
                    'Invalid WN filter');
                mask = true(size(wn));
                return
            end
            lo = min(nums(1), nums(2));
            hi = max(nums(1), nums(2));
            mask = wn >= lo & wn <= hi;
            if ~any(mask)
                uialert(app.UIFigure, ...
                    'No wavenumber points found in this range.', ...
                    'Invalid WN filter');
                mask = true(size(wn));
            end
        end
        
        % Function 29:
        function X = normalizeSpectra(app, X, method)
            method = string(method);
            switch method
                case "Min-max"
                    mn = min(X, [], 1, 'omitnan');
                    mx = max(X, [], 1, 'omitnan');
                    den = mx - mn;
                    den(den == 0) = 1;
                    X = (X - mn) ./ den;
                case "Max"
                    mx = max(abs(X), [], 1, 'omitnan');
                    mx(mx == 0) = 1;
                    X = X ./ mx;
                case "Vector norm"
                    den = sqrt(sum(X.^2, 1, 'omitnan'));
                    den(den == 0) = 1;
                    X = X ./ den;
                case "Area"
                    areaVal = trapz(app.analysisWn(:), X, 1);
                    areaVal(areaVal == 0) = 1;
                    X = X ./ areaVal;
                case "SNV"
                    mu = mean(X, 1, 'omitnan');
                    sd = std(X, 0, 1, 'omitnan');
                    sd(sd == 0) = 1;
                    X = (X - mu) ./ sd;
            end
        end
        
        % Function 30:
        function X = getSpectraByType(app)
            
            X = [];
            
            if isempty(app.analysisSpectra)
                return
            end
            
            spectralType = ...
                string(app.SpectraltypesDropDown.Value);
            
            if spectralType == ""
                return
            end
            
            % =========================================================
            % CHOOSE SOURCE DATA
            % =========================================================
            switch spectralType
                
                % -----------------------------------------------------
                % RAW
                % -----------------------------------------------------
                case { ...
                        "Raw mean spectra", ...
                        "Raw all spectra"}
                    
                    if ~isempty(app.analysisRawSpectra) && ...
                            isequal( ...
                            size(app.analysisRawSpectra), ...
                            size(app.analysisSpectra))
                        
                        X = ...
                            app.analysisRawSpectra;
                        
                    else
                        
                        % Safe fallback.
                        X = ...
                            app.analysisSpectra;
                        
                    end
                    
                    % -----------------------------------------------------
                    % UNNORMALIZED CURRENT ANALYSIS DATA
                    % -----------------------------------------------------
                otherwise
                    
                    X = ...
                        app.analysisSpectra;
                    
            end
            
            if isempty(X)
                return
            end
            
            % =========================================================
            % NORMALIZATION
            % =========================================================
            if spectralType == "Normalized mean spectra" || ...
                    spectralType == "Normalized all spectra"
                
                if app.currentNormMethod == ""
                    
                    methods = { ...
                        'Min-max', ...
                        'Max', ...
                        'Vector norm', ...
                        'Area', ...
                        'SNV'};
                    
                    [sel, ok] = listdlg( ...
                        'PromptString', ...
                        'Select normalization method:', ...
                        'SelectionMode', ...
                        'single', ...
                        'ListString', ...
                        methods, ...
                        'InitialValue', ...
                        1);
                    
                    if ~ok
                        
                        X = [];
                        return
                        
                    end
                    
                    app.currentNormMethod = ...
                        string(methods{sel});
                    
                end
                
                X = ...
                    app.normalizeSpectra( ...
                    X, ...
                    app.currentNormMethod);
                
            end
            
            % =========================================================
            % DERIVATIVES
            %
            % Derivative is calculated spectrum-by-spectrum first,
            % then mean is calculated later.
            % =========================================================
            
            
        end
        % Function 30:
        function [plotData, sds, groupNames, wnPlot] = ...
                computeGroupMeanSpectra(app)
            
            plotData = [];
            sds = [];
            groupNames = strings(0,1);
            wnPlot = [];
            
            % =========================================================
            % VALIDATE
            % =========================================================
            if isempty(app.compareGroups) || ...
                    isempty(app.analysisWn)
                
                return
            end
            
            spectralType = ...
                string(app.SpectraltypesDropDown.Value);
            
            if spectralType == ""
                return
            end
            
            % =========================================================
            % SELECT GROUPS
            % =========================================================
            selected = ...
                app.parseGroupSelection( ...
                app.GroupdataDropDown.Value);
            
            if isempty(selected)
                return
            end
            
            % =========================================================
            % SAFETY:
            % ALL SPECTRA = ONE GROUP ONLY
            % =========================================================
            if app.isAllSpectraType() && ...
                    numel(selected) ~= 1
                
                uialert( ...
                    app.UIFigure, ...
                    ['All spectra display supports only one ' ...
                    'group at a time.'], ...
                    'Group selection');
                
                return
            end
            
            % =========================================================
            % GET DATA
            % =========================================================
            X = ...
                app.getSpectraByType();
            
            if isempty(X)
                return
            end
            
            % =========================================================
            % WAVENUMBER MASK
            % =========================================================
            mask = ...
                app.getWnMaskFromFilter();
            
            if isempty(mask) || ...
                    ~any(mask)
                
                return
            end
            
            wnPlot = ...
                app.analysisWn(mask);
            
            % =========================================================
            % ALL SPECTRA
            % =========================================================
            if app.isAllSpectraType()
                
                g = ...
                    selected(1);
                
                idx = ...
                    app.compareGroups(g).Indices;
                
                idx = ...
                    idx( ...
                    idx >= 1 & ...
                    idx <= size(X,2));
                
                if isempty(idx)
                    return
                end
                
                plotData = ...
                    X(mask, idx);
                
                % No SD envelope for individual spectra.
                sds = [];
                
                baseName = ...
                    string(app.compareGroups(g).Name);
                
                groupNames = ...
                    strings(numel(idx),1);
                
                for k = 1:numel(idx)
                    
                    if idx(k) <= numel(app.analysisNames)
                        
                        groupNames(k) = ...
                            string(app.analysisNames(idx(k)));
                        
                    else
                        
                        groupNames(k) = ...
                            baseName + ...
                            " | S" + string(k);
                        
                    end
                    
                end
                
                return
            end
            
            % =========================================================
            % MEAN SPECTRA
            % =========================================================
            nG = ...
                numel(selected);
            
            plotData = ...
                NaN(numel(wnPlot), nG);
            
            sds = ...
                NaN(numel(wnPlot), nG);
            
            groupNames = ...
                strings(nG,1);
            
            for i = 1:nG
                
                g = ...
                    selected(i);
                
                idx = ...
                    app.compareGroups(g).Indices;
                
                idx = ...
                    idx( ...
                    idx >= 1 & ...
                    idx <= size(X,2));
                
                if isempty(idx)
                    continue
                end
                
                Xi = ...
                    X(mask, idx);
                
                plotData(:,i) = ...
                    mean( ...
                    Xi, ...
                    2, ...
                    'omitnan');
                
                sds(:,i) = ...
                    std( ...
                    Xi, ...
                    0, ...
                    2, ...
                    'omitnan');
                
                groupNames(i) = ...
                    string( ...
                    app.compareGroups(g).Name);
                
            end
            
            % =========================================================
            % PAIRWISE DERIVATIVE
            % =========================================================
            if spectralType == "Mean first derivative" || ...
                    spectralType == "Mean second derivative"
                
                if nG ~= 2
                    
                    uialert( ...
                        app.UIFigure, ...
                        ['Derivative comparison requires exactly ' ...
                        'two groups.'], ...
                        'Derivative comparison');
                    
                    plotData = [];
                    sds = [];
                    groupNames = strings(0,1);
                    wnPlot = [];
                    
                    return
                end
                
                wnNumeric = ...
                    double(wnPlot(:));
                
                % =====================================================
                % FIRST DERIVATIVE
                % =====================================================
                d1 = ...
                    NaN(size(plotData));
                
                for g = 1:2
                    
                    d1(:,g) = ...
                        gradient( ...
                        plotData(:,g), ...
                        wnNumeric);
                    
                end
                
                if spectralType == "Mean first derivative"
                    
                    plotData = ...
                        d1;
                    
                    % =====================================================
                    % SECOND DERIVATIVE
                    % =====================================================
                else
                    
                    d2 = ...
                        NaN(size(d1));
                    
                    for g = 1:2
                        
                        d2(:,g) = ...
                            gradient( ...
                            d1(:,g), ...
                            wnNumeric);
                        
                    end
                    
                    plotData = ...
                        d2;
                    
                end
                
                % SD shading is not directly propagated here.
                sds = [];
                
            end
            
            % =========================================================
            % MEAN DIFFERENCE
            %
            % Defined as:
            % second selected group - first selected group
            %
            % Example:
            % G1 vs G3 -> G3 - G1
            % =========================================================
            if spectralType == "Mean difference"
                
                if nG ~= 2
                    
                    uialert( ...
                        app.UIFigure, ...
                        ['Mean difference requires exactly ' ...
                        'two groups.'], ...
                        'Mean difference');
                    
                    plotData = [];
                    sds = [];
                    groupNames = strings(0,1);
                    wnPlot = [];
                    
                    return
                end
                
                plotData = ...
                    plotData(:,2) - ...
                    plotData(:,1);
                
                sds = [];
                
                groupNames = ...
                    string(app.compareGroups(selected(2)).Name) + ...
                    " - " + ...
                    string(app.compareGroups(selected(1)).Name);
                
            end
            
        end
        % Function 32:
        function plotMeanSpectraOnAxis1(app)
            
            [plotData, sds, groupNames, wnPlot] = ...
                app.computeGroupMeanSpectra();
            
            if isempty(plotData) || isempty(wnPlot)
                return
            end
            
            % =========================================================
            % RESET
            % =========================================================
            cla(app.UIAxes2, 'reset');
            
            hold(app.UIAxes2, 'on');
            
            app.UIAxes2.Box = 'on';
            
            grid(app.UIAxes2, 'off');
            
            % =========================================================
            % OPTIONS
            % =========================================================
            useOffset = ...
                strcmp(app.OffsetSwitch.Value, 'On');
            
            useSD = ...
                strcmp(app.StandardDeviationSwitch.Value, 'On');
            
            % SD is meaningless for individual all-spectra lines.
            if app.isAllSpectraType()
                useSD = false;
            end
            
            yPlot = ...
                plotData;
            
            nLines = ...
                size(yPlot,2);
            
            % =========================================================
            % OFFSET
            % =========================================================
            if useOffset && nLines > 1
                
                globalRange = ...
                    max(yPlot(:), [], 'omitnan') - ...
                    min(yPlot(:), [], 'omitnan');
                
                if ~isfinite(globalRange) || ...
                        globalRange <= 0
                    
                    globalRange = 1;
                    
                end
                
                offsetStep = ...
                    1.15 * globalRange;
                
                for i = 1:nLines
                    
                    yPlot(:,i) = ...
                        yPlot(:,i) + ...
                        (i-1)*offsetStep;
                    
                end
                
            end
            
            % =========================================================
            % DRAW
            % =========================================================
            for i = 1:nLines
                
                % -----------------------------------------------------
                % SD ENVELOPE
                % -----------------------------------------------------
                if useSD && ...
                        ~isempty(sds) && ...
                        size(sds,2) >= i
                    
                    upper = ...
                        yPlot(:,i) + sds(:,i);
                    
                    lower = ...
                        yPlot(:,i) - sds(:,i);
                    
                    fill( ...
                        app.UIAxes2, ...
                        [wnPlot; flipud(wnPlot)], ...
                        [upper; flipud(lower)], ...
                        [0.8 0.8 0.8], ...
                        'FaceAlpha', ...
                        0.25, ...
                        'EdgeColor', ...
                        'none', ...
                        'HandleVisibility', ...
                        'off');
                    
                end
                
                % -----------------------------------------------------
                % LINE
                % -----------------------------------------------------
                if i <= numel(groupNames)
                    
                    displayName = ...
                        char(groupNames(i));
                    
                else
                    
                    displayName = ...
                        sprintf('Spectrum %d', i);
                    
                end
                
                plot( ...
                    app.UIAxes2, ...
                    wnPlot, ...
                    yPlot(:,i), ...
                    'LineWidth', ...
                    1.1, ...
                    'DisplayName', ...
                    displayName);
                
            end
            
            hold(app.UIAxes2, 'off');
            
            % =========================================================
            % LABEL
            % =========================================================
            xlabel( ...
                app.UIAxes2, ...
                'Raman shift (cm^{-1})');
            
            ylabel( ...
                app.UIAxes2, ...
                'Intensity (a.u.)');
            
            title( ...
                app.UIAxes2, ...
                char(app.SpectraltypesDropDown.Value), ...
                'Interpreter', ...
                'none');
            
            % =========================================================
            % X LIMIT
            % =========================================================
            xlim( ...
                app.UIAxes2, ...
                [min(wnPlot), max(wnPlot)]);
            
            % =========================================================
            % Y LIMIT
            % =========================================================
            yFinite = ...
                yPlot(isfinite(yPlot));
            
            if ~isempty(yFinite)
                
                yl = ...
                    [min(yFinite), max(yFinite)];
                
                yr = ...
                    diff(yl);
                
                if ~isfinite(yr) || yr <= 0
                    yr = 1;
                end
                
                ylim( ...
                    app.UIAxes2, ...
                    [ ...
                    yl(1) - 0.08*yr, ...
                    yl(2) + 0.08*yr]);
                
            end
            
            % =========================================================
            % LEGEND
            % =========================================================
            if app.isAllSpectraType()
                
                % Too many individual spectra would make the legend unusable.
                legend(app.UIAxes2, 'off');
                
                cla(app.UIAxes7, 'reset');
                
                text( ...
                    app.UIAxes7, ...
                    0.5, ...
                    0.5, ...
                    sprintf('%d spectra', nLines), ...
                    'HorizontalAlignment', ...
                    'center');
                
                axis(app.UIAxes7, 'off');
                
            else
                
                legend(app.UIAxes2, 'off');
                
                app.drawLegendInSmallAxis( ...
                    app.UIAxes7, ...
                    groupNames);
                
            end
            
            % =========================================================
            % CACHE CURRENT DISPLAY
            % =========================================================
            app.currentXLim = ...
                xlim(app.UIAxes2);
            
            app.currentYLim = ...
                ylim(app.UIAxes2);
            
            app.plottedMeanSpectra = ...
                plotData;
            
            app.plottedSDSpectra = ...
                sds;
            
            app.plottedGroupNames = ...
                groupNames;
            
            app.plottedWn = ...
                wnPlot;
            
            % =========================================================
            % REAPPLY BAND CHECK IF ALREADY SELECTED
            % =========================================================
            app.applyBandCheck(false);
            
        end
        
        function cols = getBandCheckLibraryColumns(app)
            
            cols = struct( ...
                'Theory', [], ...
                'Mode', [], ...
                'Bond', [], ...
                'Assignment', [], ...
                'Association', [], ...
                'Note', [], ...
                'Reference', []);
            
            if isempty(app.peakLibrary) || ...
                    ~istable(app.peakLibrary)
                
                return
            end
            
            vars = ...
                string( ...
                app.peakLibrary.Properties.VariableNames);
            
            normVars = ...
                lower( ...
                regexprep( ...
                strtrim(vars), ...
                '[^a-zA-Z0-9]', ...
                ''));
            
            cols.Theory = ...
                find( ...
                normVars == "theoreticalpeak", ...
                1);
            
            cols.Mode = ...
                find( ...
                normVars == "vibrationalmode", ...
                1);
            
            cols.Bond = ...
                find( ...
                normVars == "bondfunctionalgroup", ...
                1);
            
            cols.Assignment = ...
                find( ...
                normVars == "molecularassignment", ...
                1);
            
            cols.Association = ...
                find( ...
                normVars == "biochemicalcellularassociation", ...
                1);
            
            cols.Note = ...
                find( ...
                normVars == "interpretationnote", ...
                1);
            
            cols.Reference = ...
                find( ...
                normVars == "reference", ...
                1);
            
        end


        function [centerWN, lowWN, highWN] = ...
                parseBandLibraryRange(app, value)
            
            centerWN = NaN;
            lowWN = NaN;
            highWN = NaN;
            
            txt = ...
                strtrim(string(value));
            
            if ismissing(txt) || txt == ""
                return
            end
            
            numsText = ...
                regexp( ...
                char(txt), ...
                '\d+(?:\.\d+)?', ...
                'match');
            
            if isempty(numsText)
                return
            end
            
            nums = ...
                str2double(numsText);
            
            nums = ...
                nums(isfinite(nums));
            
            if isempty(nums)
                return
            end
            
            if numel(nums) == 1
                
                centerWN = nums(1);
                
                lowWN = ...
                    centerWN - 5;
                
                highWN = ...
                    centerWN + 5;
                
            else
                
                lowWN = ...
                    min(nums(1), nums(2));
                
                highWN = ...
                    max(nums(1), nums(2));
                
                centerWN = ...
                    mean([lowWN highWN]);
                
            end
            
        end


        function refreshBandCheckList(app)
            
            app.ListBox.Items = {'All'};
            app.ListBox.Value = {'All'};
            
            app.TextArea_7.Value = ...
                {'No peak selected.'};
            
            if isempty(app.peakLibrary) || ...
                    ~istable(app.peakLibrary)
                
                return
            end
            
            cols = ...
                app.getBandCheckLibraryColumns();
            
            if isempty(cols.Theory)
                return
            end
            
            n = ...
                height(app.peakLibrary);
            
            observed = ...
                NaN(n,1);
            
            % =========================================================
            % DEFINED OBSERVED PEAKS
            % =========================================================
            if isstruct(app.spectralDef) && ...
                    isfield(app.spectralDef, 'peakObservedWN')
                
                candidate = ...
                    double( ...
                    app.spectralDef.peakObservedWN(:));
                
                if numel(candidate) == n
                    
                    observed = ...
                        candidate;
                    
                end
                
            end
            
            theory = ...
                strtrim( ...
                string( ...
                app.peakLibrary{:,cols.Theory}));
            
            items = ...
                strings(0,1);
            
            validRows = [];
            
            for k = 1:n
                
                if theory(k) == "" || ...
                        ismissing(theory(k))
                    
                    continue
                end
                
                if isfinite(observed(k))
                    
                    label = sprintf( ...
                        '%d | %s | Obs %.1f', ...
                        k, ...
                        char(theory(k)), ...
                        observed(k));
                    
                else
                    
                    label = sprintf( ...
                        '%d | %s', ...
                        k, ...
                        char(theory(k)));
                    
                end
                
                items(end+1,1) = ...
                    string(label);
                
                validRows(end+1,1) = ...
                    k;
                
            end
            
            % =========================================================
            % Store row mapping in UserData
            % =========================================================
            app.ListBox.UserData = ...
                validRows;
            
            app.ListBox.Items = ...
                cellstr(["All"; items]);
            
            app.ListBox.Value = ...
                {'All'};
            
        end


        % Function 33:
        function updateSpectralTypeAvailability(app)
            
            % =========================================================
            % SPECTRAL TYPES AVAILABLE FOR MEAN SPECTRA TAB
            % =========================================================
            
            items = { ...
                '', ...
                'Raw mean spectra', ...
                'Unnormalized mean spectra', ...
                'Normalized mean spectra', ...
                'Raw all spectra', ...
                'Unnormalized all spectra', ...
                'Normalized all spectra', ...
                'Mean first derivative', ...
                'Mean second derivative'};
            
            % Mean difference only makes sense for exactly two
            % comparison groups.
            if numel(app.compareGroups) == 2
                
                items{end+1} = ...
                    'Mean difference';
                
            end
            
            oldValue = ...
                string(app.SpectraltypesDropDown.Value);
            
            app.SpectraltypesDropDown.Items = ...
                items;
            
            % Preserve current selection only if still valid.
            if oldValue ~= "" && ...
                    any(strcmp(string(items), oldValue))
                
                app.SpectraltypesDropDown.Value = ...
                    char(oldValue);
                
            else
                
                app.SpectraltypesDropDown.Value = '';
                
            end
            
        end
        
        
        function tf = isAllSpectraType(app)
            
            spectralType = ...
                lower(strtrim( ...
                string(app.SpectraltypesDropDown.Value)));
            
            tf = ...
                contains(spectralType, "all spectra");
            
        end


        function updateMeanGroupSelectionAvailability(app)
            
            % =========================================================
            % GROUP DATA RULE
            %
            % Normal mean spectra:
            %   one or multiple groups
            %
            % All spectra:
            %   exactly one group
            %
            % Derivative / Difference:
            %   exactly one pair of groups
            % =========================================================
            
            if isempty(app.compareGroups)
                
                app.GroupdataDropDown.Items = {''};
                app.GroupdataDropDown.Value = '';
                
                return
            end
            
            nGroups = ...
                numel(app.compareGroups);
            
            items = {''};
            
            % =========================================================
            % CASE 1 — ALL SPECTRA
            % ONE GROUP ONLY
            % =========================================================
            if app.isAllSpectraType()
                
                for g = 1:nGroups
                    
                    items{end+1} = ...
                        sprintf('G%d', g);
                    
                end
                
                % =========================================================
                % CASE 2 — DERIVATIVE / DIFFERENCE
                % EXACTLY TWO GROUPS
                % =========================================================
            elseif app.isPairwiseSpectralType()
                
                if nGroups < 2
                    
                    app.GroupdataDropDown.Items = {''};
                    app.GroupdataDropDown.Value = '';
                    
                    return
                end
                
                pairList = ...
                    nchoosek(1:nGroups, 2);
                
                for r = 1:size(pairList,1)
                    
                    g1 = pairList(r,1);
                    g2 = pairList(r,2);
                    
                    items{end+1} = ...
                        sprintf( ...
                        'G%d vs G%d', ...
                        g1, ...
                        g2);
                    
                end
                
                % =========================================================
                % CASE 3 — NORMAL MEAN SPECTRA
                % =========================================================
            else
                
                if nGroups > 1
                    
                    items{end+1} = ...
                        'All groups';
                    
                end
                
                % Individual groups
                for g = 1:nGroups
                    
                    items{end+1} = ...
                        sprintf('G%d', g);
                    
                end
                
                % Multiple-group combinations
                if nGroups >= 2
                    
                    for nSelect = 2:nGroups
                        
                        C = ...
                            nchoosek( ...
                            1:nGroups, ...
                            nSelect);
                        
                        for r = 1:size(C,1)
                            
                            parts = ...
                                "G" + string(C(r,:));
                            
                            items{end+1} = ...
                                char(strjoin(parts, " + "));
                            
                        end
                        
                    end
                    
                end
                
            end
            
            % =========================================================
            % ASSIGN
            % =========================================================
            oldValue = ...
                string(app.GroupdataDropDown.Value);
            
            app.GroupdataDropDown.Items = ...
                items;
            
            if oldValue ~= "" && ...
                    any(strcmp(string(items), oldValue))
                
                app.GroupdataDropDown.Value = ...
                    char(oldValue);
                
            else
                
                app.GroupdataDropDown.Value = '';
                
            end
            
        end


        function [X, groupLabels, groupNames, spectrumNames] = buildPCAInputFromLabeledGroups(app)
            if isempty(app.analysisSpectra)
                error('Please run Review data structure first in Mean spectra tab.');
            end
            if isempty(app.compareGroups)
                error('Please run Label groups first.');
            end
            Xall = app.analysisSpectra;
            nSpectra = size(Xall, 2);
            usedIdx = [];
            groupLabels = strings(0,1);
            spectrumNames = strings(0,1);
            groupNames = strings(numel(app.compareGroups),1);
            for g = 1:numel(app.compareGroups)
                idx = app.compareGroups(g).Indices(:)';
                idx = idx(idx >= 1 & idx <= nSpectra);
                usedIdx = [usedIdx, idx];
                groupLabels = [groupLabels; repmat(string(app.compareGroups(g).Name), numel(idx), 1)];
                groupNames(g) = string(app.compareGroups(g).Name);
                if ~isempty(app.analysisNames) && numel(app.analysisNames) >= max(idx)
                    spectrumNames = [spectrumNames; app.analysisNames(idx(:))];
                else
                    spectrumNames = [spectrumNames; "Spectrum_" + string(idx(:))];
                end
            end
            X = Xall(:, usedIdx)';  % rows = spectra, columns = wavenumbers
        end
        
        
        function plotPCScorePair(app,pcx,pcy)
            app.currentPCX = pcx;
            app.currentPCY = pcy;
            cla(app.UIAxes4,'reset');
            cla(app.UIAxes6,'reset');
            hold(app.UIAxes4,'on');
            xline(app.UIAxes4,0,'--k','HandleVisibility','off');
            yline(app.UIAxes4,0,'--k','HandleVisibility','off');
            groupNames = unique(app.pcaGroupLabels,'stable');
            scoreSize = app.ScoresizeSlider.Value;
            allX = app.pcaScores(:,pcx);
            allY = app.pcaScores(:,pcy);
            for g = 1:numel(groupNames)
                mask = app.pcaGroupLabels == groupNames(g);
                scatter(app.UIAxes4,app.pcaScores(mask,pcx),app.pcaScores(mask,pcy),scoreSize,'filled','DisplayName',char(groupNames(g)));
            end
            if app.LocateclustersCheckBox.Value
                [ex,ey] = app.drawPCAClusterEllipses(pcx,pcy);
                allX = [allX;ex(:)];
                allY = [allY;ey(:)];
            end
            hold(app.UIAxes4,'off');
            box(app.UIAxes4,'on');
            grid(app.UIAxes4,'off');
            xlabel(app.UIAxes4,sprintf('PC%d (%.2f%%)',pcx,app.pcaExplained(pcx)));
            ylabel(app.UIAxes4,sprintf('PC%d (%.2f%%)',pcy,app.pcaExplained(pcy)));
            title(app.UIAxes4,sprintf('PCA score plot: PC%d vs PC%d',pcx,pcy));
            allX = allX(isfinite(allX));
            allY = allY(isfinite(allY));
            xl = [min(allX),max(allX)];
            yl = [min(allY),max(allY)];
            xr = diff(xl); yr = diff(yl);
            if xr == 0 || isnan(xr), xr = 1; end
            if yr == 0 || isnan(yr), yr = 1; end
            xlim(app.UIAxes4,[xl(1)-0.12*xr,xl(2)+0.12*xr]);
            ylim(app.UIAxes4,[yl(1)-0.12*yr,yl(2)+0.12*yr]);
            app.drawLegendInSmallAxis(app.UIAxes6,groupNames);
        end
        

        function plotPCLoadings(app, pcs)
            pcs = unique(pcs(:)', 'stable');
            pcs = pcs(pcs >= 1 & pcs <= size(app.pcaLoadings, 2));
            
            if isempty(pcs)
                return
            end
            
            app.currentLoadingPCs = pcs;
            
            mask = app.getPCALoadingWnMask();
            wnPlot = app.pcaWn(mask);
            
            cla(app.UIAxes5, 'reset');
            cla(app.UIAxes6_2, 'reset');
            hold(app.UIAxes5, 'on');
            
            L = app.pcaLoadings(mask, pcs);
            
            if numel(pcs) == 1
                offsets = 0;
            else
                yRange = max(L(:), [], 'omitnan') - min(L(:), [], 'omitnan');
                if yRange == 0 || isnan(yRange)
                    yRange = 1;
                end
                offsets = (0:numel(pcs)-1) * 1.25 * yRange;
            end
            
            legendNames = strings(numel(pcs), 1);
            allY = [];
            
            for k = 1:numel(pcs)
                pc = pcs(k);
                y = app.pcaLoadings(mask, pc) + offsets(k);
                
                yline(app.UIAxes5, offsets(k), '--k', 'HandleVisibility', 'off');
                
                plot(app.UIAxes5, wnPlot, y, ...
                    'LineWidth', 1.8, ...
                    'DisplayName', sprintf('PC%d loading', pc));
                
                legendNames(k) = sprintf('PC%d loading', pc);
                allY = [allY; y(:)];
            end
            
            hold(app.UIAxes5, 'off');
            
            box(app.UIAxes5, 'on');
            grid(app.UIAxes5, 'off');
            
            if app.pcaInputType == "Ratios" && numel(app.pcaVariableNames) == numel(app.pcaWn)
                xlabel(app.UIAxes5, 'Ratio variable');
                xticks(app.UIAxes5, app.pcaWn);
                xticklabels(app.UIAxes5, cellstr(app.pcaVariableNames));
                app.UIAxes5.XTickLabelRotation = 35;
            else
                xlabel(app.UIAxes5, 'Wavenumber (cm^{-1})');
            end
            
            ylabel(app.UIAxes5, 'Loading intensity');
            title(app.UIAxes5, 'PCA loadings');
            
            if ~isempty(wnPlot)
                xlim(app.UIAxes5, [min(wnPlot), max(wnPlot)]);
            end
            
            yl = [min(allY, [], 'omitnan'), max(allY, [], 'omitnan')];
            yr = diff(yl);
            
            if yr == 0 || isnan(yr)
                yr = 1;
            end
            
            ylim(app.UIAxes5, [yl(1)-0.2*yr, yl(2)+0.2*yr]);
            
            if app.HighpeaksCheckBox.Value && numel(pcs) == 1 && app.pcaInputType ~= "Ratios"
                app.highlightHighLoadingPeaks(pcs(1), offsets(1));
            end
            
            app.drawLegendInSmallAxis(app.UIAxes6_2, legendNames);
        end
        
        function xl = getDisplayXLim(app)
            if ~isempty(app.spectralDef) && isfield(app.spectralDef,'roiMask') && numel(app.spectralDef.roiMask)==numel(app.wn) && any(app.spectralDef.roiMask)
                x = app.wn(app.spectralDef.roiMask);
                xl = [min(x),max(x)];
            elseif ~isempty(app.spectralDef) && isfield(app.spectralDef,'roiRanges') && ~isempty(app.spectralDef.roiRanges)
                xl = [min(app.spectralDef.roiRanges(:)),max(app.spectralDef.roiRanges(:))];
            else
                xl = [min(app.wn),max(app.wn)];
            end
        end
        
        function populatePCATrees(app)
            delete(app.Tree.Children);
            delete(app.Tree2.Children);
            if isempty(app.pcaScores) || isempty(app.pcaLoadings) || isempty(app.pcaExplained)
                return
            end
            nPC = size(app.pcaScores,2);
            maxPCShow = min(nPC,5);
            rootScore = uitreenode(app.Tree);
            rootScore.Text = sprintf('PC score plots: PC1-PC%d',maxPCShow);
            rootScore.NodeData = [];
            for i = 1:maxPCShow-1
                for j = i+1:maxPCShow
                    node = uitreenode(rootScore);
                    node.Text = sprintf('PC%d vs PC%d',i,j);
                    node.NodeData = struct('Type',"score",'PCX',i,'PCY',j);
                end
            end
            expand(rootScore);
            rootLoading = uitreenode(app.Tree2);
            rootLoading.Text = sprintf('PC loadings: PC1-PC%d',maxPCShow);
            rootLoading.NodeData = [];
            for i = 1:maxPCShow
                node = uitreenode(rootLoading);
                node.Text = sprintf('PC%d loading (%.2f%%)',i,app.pcaExplained(i));
                node.NodeData = struct('Type',"loading",'PC',i);
            end
            expand(rootLoading);
        end
        
        
        function drawLegendInSmallAxis(app,ax,names)
            cla(ax,'reset');
            names = string(names(:));
            axis(ax,'off');
            box(ax,'on');
            ax.Visible = 'on';
            ax.XTick = [];
            ax.YTick = [];
            if isempty(names)
                return
            end
            n = numel(names);
            y = linspace(0.85,0.15,n);
            hold(ax,'on');
            for k = 1:n
                plot(ax,[0.08 0.25],[y(k) y(k)],'LineWidth',3);
                text(ax,0.32,y(k),char(names(k)),'Interpreter','none','FontSize',12,'FontWeight','bold','VerticalAlignment','middle');
            end
            hold(ax,'off');
            xlim(ax,[0 1]);
            ylim(ax,[0 1]);
        end
        
        function [allEllipseX,allEllipseY] = drawPCAClusterEllipses(app,pcx,pcy)
            groupNames = unique(app.pcaGroupLabels,'stable');
            theta = linspace(0,2*pi,240);
            co = app.UIAxes4.ColorOrder;
            allEllipseX = [];
            allEllipseY = [];
            for g = 1:numel(groupNames)
                mask = app.pcaGroupLabels == groupNames(g);
                x = app.pcaScores(mask,pcx);
                y = app.pcaScores(mask,pcy);
                x = x(:); y = y(:);
                ok = isfinite(x) & isfinite(y);
                x = x(ok); y = y(ok);
                if numel(x) < 3
                    continue
                end
                mu = [mean(x),mean(y)];
                C = cov([x y]);
                if any(~isfinite(C(:))) || rank(C) < 2
                    continue
                end
                [V,D] = eig(C);
                pts = mu(:) + 2.2 * V * sqrt(D) * [cos(theta);sin(theta)];
                thisColor = co(mod(g-1,size(co,1))+1,:);
                darkColor = max(thisColor * 0.45, 0);
                plot(app.UIAxes4,pts(1,:),pts(2,:),'Color',darkColor,'LineWidth',1.6,'HandleVisibility','off');
                allEllipseX = [allEllipseX;pts(1,:)'];
                allEllipseY = [allEllipseY;pts(2,:)'];
            end
        end


        function highlightHighLoadingPeaks(app,pc,offset)
            txt = strtrim(string(app.peakEditField.Value));
            if txt == ""
                pct = 10;
                app.peakEditField.Value = "10";
            else
                pct = str2double(txt);
            end
            if isnan(pct) || pct <= 0 || pct > 100
                uialert(app.UIFigure,'Enter % peak from 0-100. Example: 10 means top 10% strongest loading peaks.','Invalid % peak');
                return
            end
            wn = app.pcaWn(:);
            y0 = app.pcaLoadings(:,pc);
            absY = abs(y0);
            thr = prctile(absY,100-pct);
            minProm = 0.05 * max(absY,[],'omitnan');
            [pksMax,locMax] = findpeaks(y0,'MinPeakHeight',thr,'MinPeakProminence',minProm);
            [pksMin,locMin] = findpeaks(-y0,'MinPeakHeight',thr,'MinPeakProminence',minProm);
            locs = [locMax(:);locMin(:)];
            amps = [abs(pksMax(:));abs(pksMin(:))];
            if isempty(locs)
                return
            end
            [~,ord] = sort(amps,'descend');
            maxN = min(12,numel(ord));
            locs = locs(ord(1:maxN));
            yl = ylim(app.UIAxes5);
            dx = median(abs(diff(wn)),'omitnan');
            if isnan(dx) || dx <= 0
                dx = (max(wn)-min(wn))/numel(wn);
            end
            bandHalfWidth = 4 * dx;
            for i = 1:numel(locs)
                x0 = wn(locs(i));
                patch(app.UIAxes5,[x0-bandHalfWidth x0+bandHalfWidth x0+bandHalfWidth x0-bandHalfWidth],[yl(1) yl(1) yl(2) yl(2)],[0.5 0.5 0.5],'FaceAlpha',0.18,'EdgeColor','none','HandleVisibility','off');
            end
        end
        
        
        function mask = getPCALoadingWnMask(app)
            wn = app.pcaWn(:);
            mask = true(size(wn));
            txt = strtrim(string(app.EditField_3.Value));
            if txt == ""
                return
            end
            nums = regexp(char(txt),'[-+]?\d*\.?\d+','match');
            nums = str2double(nums);
            if numel(nums) < 2 || any(isnan(nums(1:2)))
                uialert(app.UIFigure,'Enter WN filter as: start end, for example: 800 1800','Invalid WN filter');
                return
            end
            lo = min(nums(1),nums(2));
            hi = max(nums(1),nums(2));
            newMask = wn >= lo & wn <= hi;
            if ~any(newMask)
                uialert(app.UIFigure,'No loading points found in this WN range.','Invalid WN filter');
                return
            end
            mask = newMask;
        end


        function saveSelectedAnalysisAxes(app, modeText)
            
            % =========================================================
            % Ask resolution
            % =========================================================
            answer = inputdlg( ...
                {'Enter image resolution DPI:'}, ...
                'Save image', ...
                [1 45], ...
                {'300'});
            
            
            if isempty(answer)
                return
            end
            
            
            dpi = str2double(answer{1});
            
            
            if isnan(dpi) || ...
                    ~isfinite(dpi) || ...
                    dpi <= 0
                
                uialert( ...
                    app.UIFigure, ...
                    'DPI must be a positive number.', ...
                    'Invalid DPI');
                
                return
                
            end
            
            
            % =========================================================
            % Output folder
            % =========================================================
            outFolder = ...
                app.getProjectSubFolder( ...
                "Images", ...
                modeText);
            
            
            if outFolder == ""
                return
            end
            
            
            % =========================================================
            % Publication profile
            % =========================================================
            profile = ...
                app.choosePublicationProfile();
            
            
            if profile == ""
                return
            end
            
            
            % =========================================================
            % Timestamp
            % =========================================================
            timestamp = char( ...
                datetime( ...
                'now', ...
                'Format', ...
                'yyyy-MM-dd_HH-mm-ss'));
            
            
            % =========================================================
            % Available figures
            % =========================================================
            switch char(modeText)
                
                case 'Mean analysis'
                    
                    choices = { ...
                        'Mean spectra axis', ...
                        'Mean legend', ...
                        'All mean page'};
                    
                    
                case 'PCA analysis'
                    
                    choices = { ...
                        'PCA score axis', ...
                        'PCA score legend', ...
                        'PCA loading axis', ...
                        'PCA loading legend', ...
                        'All PCA page'};
                    
                    
                case 'Classification analysis'
                    
                    choices = { ...
                        'Classification score axis', ...
                        'Classification score legend', ...
                        'Classification loading axis', ...
                        'Classification loading legend', ...
                        'All classification page'};
                    
                    
                otherwise
                    
                    choices = {'Axis'};
                    
            end
            
            
            % =========================================================
            % Choose items
            % =========================================================
            [sel, ok] = listdlg( ...
                'PromptString', ...
                'Select figures to save:', ...
                'SelectionMode', ...
                'multiple', ...
                'ListString', ...
                choices, ...
                'InitialValue', ...
                1:numel(choices), ...
                'ListSize', ...
                [320 220]);
            
            
            if ~ok || isempty(sel)
                return
            end
            
            
            savedFiles = strings(0,1);
            
            
            % =========================================================
            % Export selected objects
            % =========================================================
            for i = 1:numel(sel)
                
                choice = choices{sel(i)};
                
                
                targetObj = [];
                defaultName = "";
                isPage = false;
                
                
                % =====================================================
                % Resolve target
                % =====================================================
                switch choice
                    
                    % -------------------------------------------------
                    % Mean analysis
                    % -------------------------------------------------
                    case 'Mean spectra axis'
                        
                        targetObj = app.UIAxes2;
                        defaultName = ...
                            "mean_spectra_axis";
                        
                        
                    case 'Mean legend'
                        
                        targetObj = app.UIAxes7;
                        defaultName = ...
                            "mean_spectra_legend";
                        
                        
                    case 'All mean page'
                        
                        targetObj = app.MeanspectraTab;
                        defaultName = ...
                            "mean_analysis_page";
                        
                        isPage = true;
                        
                        
                        % -------------------------------------------------
                        % PCA analysis
                        % -------------------------------------------------
                    case 'PCA score axis'
                        
                        targetObj = app.UIAxes4;
                        defaultName = ...
                            "pca_score_axis";
                        
                        
                    case 'PCA score legend'
                        
                        targetObj = app.UIAxes6;
                        defaultName = ...
                            "pca_score_legend";
                        
                        
                    case 'PCA loading axis'
                        
                        targetObj = app.UIAxes5;
                        defaultName = ...
                            "pca_loading_axis";
                        
                        
                    case 'PCA loading legend'
                        
                        targetObj = app.UIAxes6_2;
                        defaultName = ...
                            "pca_loading_legend";
                        
                        
                    case 'All PCA page'
                        
                        targetObj = app.PCATab;
                        defaultName = ...
                            "pca_analysis_page";
                        
                        isPage = true;
                        
                        
                        % -------------------------------------------------
                        % Classification analysis
                        % -------------------------------------------------
                    case 'Classification score axis'
                        
                        targetObj = app.UIAxes4_2;
                        defaultName = ...
                            "classification_score_axis";
                        
                        
                    case 'Classification score legend'
                        
                        targetObj = app.UIAxes6_3;
                        defaultName = ...
                            "classification_score_legend";
                        
                        
                    case 'Classification loading axis'
                        
                        targetObj = app.UIAxes5_2;
                        defaultName = ...
                            "classification_loading_axis";
                        
                        
                    case 'Classification loading legend'
                        
                        targetObj = app.UIAxes6_4;
                        defaultName = ...
                            "classification_loading_legend";
                        
                        
                    case 'All classification page'
                        
                        targetObj = app.ClassificationTab;
                        defaultName = ...
                            "classification_analysis_page";
                        
                        isPage = true;
                        
                        
                    otherwise
                        
                        continue
                        
                end
                
                
                % =====================================================
                % Validate target
                % =====================================================
                if isempty(targetObj) || ...
                        ~isvalid(targetObj)
                    
                    continue
                    
                end
                
                
                % =====================================================
                % File path
                % =====================================================
                filePath = fullfile( ...
                    outFolder, ...
                    char( ...
                    defaultName + ...
                    "_" + ...
                    string(timestamp) + ...
                    ".png"));
                
                
                % =====================================================
                % Export
                % =====================================================
                try
                    
                    if isPage
                        
                        % Whole page = app snapshot
                        exportgraphics( ...
                            targetObj, ...
                            filePath, ...
                            'Resolution', ...
                            dpi, ...
                            'BackgroundColor', ...
                            'white');
                        
                    else
                        
                        % Individual axis = publication export
                        app.exportAxesPublication( ...
                            targetObj, ...
                            filePath, ...
                            dpi, ...
                            profile);
                        
                    end
                    
                    
                    savedFiles(end+1,1) = ...
                        string(filePath); %#ok<AGROW>
                    
                    
                catch ME
                    
                    uialert( ...
                        app.UIFigure, ...
                        sprintf( ...
                        'Cannot save "%s".\n\n%s', ...
                        choice, ...
                        ME.message), ...
                        'Save image error');
                    
                end
                
            end
            
            
            % =========================================================
            % Completion message
            % =========================================================
            if isempty(savedFiles)
                
                uialert( ...
                    app.UIFigure, ...
                    'No image was saved.', ...
                    'Save image');
                
                return
                
            end
            
            
            msgbox( ...
                sprintf( ...
                '%d image(s) saved successfully.\n\nFolder:\n%s', ...
                numel(savedFiles), ...
                outFolder), ...
                'Save images');
            
        end
        
        function [ ...
                X, ...
                groupLabels, ...
                groupNames, ...
                spectrumNames, ...
                variableNames] = ...
                buildPCAInputFromSelectedRatios(app)
            
            X = [];
            
            groupLabels = strings(0,1);
            
            groupNames = strings(0,1);
            
            spectrumNames = strings(0,1);
            
            variableNames = strings(0,1);
            
            
            % =============================================================
            % 1. SOURCE SPECTRA
            % =============================================================
            
            [ ...
                spectralX, ...
                groupLabels, ...
                groupNames, ...
                spectrumNames] = ...
                app.buildPCAInputFromLabeledGroups();
            
            
            if isempty(spectralX)
                return
            end
            
            
            app.ensureRatioDefinitionSchema();
            
            
            % =============================================================
            % 2. SAVED RECIPES
            % =============================================================
            
            candidates = ...
                app.ratioDefinitions;
            
            
            % =============================================================
            % 3. MENU
            % =============================================================
            
            displayText = ...
                strings(height(candidates)+1,1);
            
            
            for r = 1:height(candidates)
                
                if candidates.DefinitionType(r) == ...
                        "CompositeRatio"
                    
                    typeLabel = ...
                        "Score";
                    
                else
                    
                    typeLabel = ...
                        "Ratio";
                    
                end
                
                
                displayText(r) = ...
                    sprintf( ...
                    '[Saved %s] %s | %s | %s', ...
                    typeLabel, ...
                    candidates.RatioName(r), ...
                    candidates.Formula(r), ...
                    candidates.FeatureType(r));
                
            end
            
            
            displayText(end) = ...
                "< Add new ratio / composite score... >";
            
            
            [sel, accepted] = ...
                listdlg( ...
                'PromptString', ...
                'Select at least 2 ratio/score variables for PCA:', ...
                'SelectionMode', ...
                'multiple', ...
                'ListString', ...
                cellstr(displayText), ...
                'ListSize', ...
                [620 390]);
            
            
            if ~accepted || isempty(sel)
                return
            end
            
            
            addIndex = ...
                numel(displayText);
            
            
            % =============================================================
            % 4. CREATE NEW RECIPE
            % =============================================================
            
            if any(sel == addIndex)
                
                [newDef, okNew] = ...
                    app.promptNewRatioDefinition();
                
                
                if okNew
                    
                    [added, messageText] = ...
                        app.addRatioDefinitionInternal( ...
                        newDef);
                    
                    
                    if added
                        
                        app.saveAnalysisMetadata(false);
                        
                    end
                    
                    
                    msgbox( ...
                        char(messageText), ...
                        'Ratio / score');
                    
                end
                
                
                [ ...
                    X, ...
                    groupLabels, ...
                    groupNames, ...
                    spectrumNames, ...
                    variableNames] = ...
                    app.buildPCAInputFromSelectedRatios();
                
                return
                
            end
            
            
            % =============================================================
            % 5. REQUIRE >=2 VARIABLES FOR PCA
            % =============================================================
            
            if numel(sel) < 2
                
                uialert( ...
                    app.UIFigure, ...
                    'Ratio PCA requires at least 2 ratio/score variables.', ...
                    'PCA');
                
                return
                
            end
            
            
            selectedDefs = ...
                candidates(sel,:);
            
            
            % =============================================================
            % 6. RECOMPUTE FROM SOURCE
            % =============================================================
            
            nSamples = ...
                size(spectralX,1);
            
            
            nVariables = ...
                height(selectedDefs);
            
            
            X = ...
                nan( ...
                nSamples, ...
                nVariables);
            
            
            variableNames = ...
                strings( ...
                nVariables, ...
                1);
            
            
            for r = 1:nVariables
                
                try
                    
                    X(:,r) = ...
                        app.computeRatioDefinitionVector( ...
                        selectedDefs(r,:), ...
                        spectralX, ...
                        app.analysisWn);
                    
                catch ME
                    
                    uialert( ...
                        app.UIFigure, ...
                        sprintf( ...
                        'Cannot compute "%s":\n\n%s', ...
                        selectedDefs.RatioName(r), ...
                        ME.message), ...
                        'Ratio PCA');
                    
                    X = [];
                    
                    return
                    
                end
                
                
                variableNames(r) = ...
                    selectedDefs.RatioName(r);
                
            end
            
            
            % =============================================================
            % 7. COMPLETE-CASE ROWS
            % =============================================================
            
            badRows = ...
                any(~isfinite(X),2);
            
            
            badRows = ...
                logical(badRows(:));
            
            
            X(badRows,:) = [];
            
            groupLabels(badRows) = [];
            
            spectrumNames(badRows) = [];
            
            
            % =============================================================
            % 8. RECIPE
            % =============================================================
            
            app.pcaRunRecipe.RatioDefinitions = ...
                selectedDefs;
            
            
            app.pcaRunRecipe.VariableNames = ...
                variableNames;
            
        end


        function S = collectSessionState(app)
            S = struct();
            S.savedAt = char(datetime('now'));
            S.appVersion = 'Raman_session_v1';
            S.mode = app.mode;
            S.wn = app.wn;
            S.spectra = app.spectra;
            S.cube = app.cube;
            S.rawSpectra = app.rawSpectra;
            S.rawCube = app.rawCube;
            S.names = app.names;
            S.rawNames = app.rawNames;
            S.projectName = app.projectName;
            S.projectFolder = app.projectFolder;
            S.manageDataFolder = app.manageDataFolder;
            S.shouldSaveData = app.shouldSaveData;
            S.processHistory = app.processHistory;
            S.cosmicRemoved = app.cosmicRemoved;
            S.lastBandMode = app.lastBandMode;
            S.lastBandWn = app.lastBandWn;
            S.lastBandLow = app.lastBandLow;
            S.lastBandHigh = app.lastBandHigh;
            S.spectralDef = app.spectralDef;
            S.dataReviewState = app.dataReviewState;
            S.singleData = app.singleData;
            S.singleGroupTable = app.singleGroupTable;
            S.singleFileTable = app.singleFileTable;
            S.singleLastSelectedGroups = app.singleLastSelectedGroups;
            S.peakLibrary = app.peakLibrary;
            S.analysisSource = app.analysisSource;
            S.analysisWn = app.analysisWn;
            S.analysisSpectra = app.analysisSpectra;
            S.analysisRawSpectra = app.analysisRawSpectra;
            S.analysisNames = app.analysisNames;
            S.analysisHistory = app.analysisHistory;
            S.baseGroups = app.baseGroups;
            S.compareGroups = app.compareGroups;
            S.currentSpectralType = app.currentSpectralType;
            S.currentNormMethod = app.currentNormMethod;
            S.currentGroupSelection = app.currentGroupSelection;
            S.plottedMeanSpectra = app.plottedMeanSpectra;
            S.plottedSDSpectra = app.plottedSDSpectra;
            S.plottedGroupNames = app.plottedGroupNames;
            S.plottedWn = app.plottedWn;
            S.ratioFormula = app.ratioFormula;
            S.ratioBands = app.ratioBands;
            S.ratioResults = app.ratioResults;
            S.boxplotResults = app.boxplotResults;
            S.ratioDefinitions = app.ratioDefinitions;
            S.peakLibrary = app.peakLibrary;
            S.pcaInputType = app.pcaInputType;
            S.pcaVariableNames = app.pcaVariableNames;
            S.pcaX = app.pcaX;
            S.pcaSourceX = ...
                app.pcaSourceX;
            S.pcaReferenceValues = ...
                app.pcaReferenceValues;
            S.pcaReferenceScaledX = ...
                app.pcaReferenceScaledX;
            S.pcaReferenceDefinition = ...
                app.pcaReferenceDefinition;
            S.pcaReferenceFeatureType = ...
                app.pcaReferenceFeatureType;
            S.pcaWn = app.pcaWn;
            S.pcaScores = app.pcaScores;
            S.pcaLoadings = app.pcaLoadings;
            S.pcaExplained = app.pcaExplained;
            S.pcaGroupLabels = app.pcaGroupLabels;
            S.pcaGroupNames = app.pcaGroupNames;
            S.pcaSpectrumNames = app.pcaSpectrumNames;
            S.pcaNormMethod = app.pcaNormMethod;
            S.currentPCX = app.currentPCX;
            S.currentPCY = app.currentPCY;
            S.currentLoadingPCs = app.currentLoadingPCs;
            S.ratioScreeningSelectedPeaks = app.ratioScreeningSelectedPeaks;
            S.ratioScreeningAll = app.ratioScreeningAll;
            S.ratioScreeningTop = app.ratioScreeningTop;
            S.ratioScreeningValues = app.ratioScreeningValues;
            S.ratioScreeningGroupLabels = app.ratioScreeningGroupLabels;
            S.ratioScreeningGroupNames = app.ratioScreeningGroupNames;
            S.ratioScreeningSpectrumNames = app.ratioScreeningSpectrumNames;
            % ===== Scan analysis session =====
            S.scanLoaded = app.scanLoaded;
            S.scanWn = app.scanWn;
            S.scanRawCube = app.scanRawCube;
            S.scanProcessedCube = app.scanProcessedCube;
            S.scanBandLow = app.scanBandLow;
            S.scanBandHigh = app.scanBandHigh;
            S.scanCurrentAxis1Map = app.scanCurrentAxis1Map;
            S.scanCurrentAxis2Map = app.scanCurrentAxis2Map;
            S.scanCurrentAxis1Title = app.scanCurrentAxis1Title;
            S.scanCurrentAxis2Title = app.scanCurrentAxis2Title;
            S.scanCurrentFeatureMap = app.scanCurrentFeatureMap;
            S.scanCurrentFeatureName = app.scanCurrentFeatureName;
            S.scanCurrentFeatureType = app.scanCurrentFeatureType;
            S.scanCurrentFeatureFormula = app.scanCurrentFeatureFormula;
            S.scanFeatures = app.scanFeatures;
            S.scanBandRanges = app.scanBandRanges;
            S.scanBandCombineMode = app.scanBandCombineMode;
            S.scanBandDisplayText = app.scanBandDisplayText;
            S.scanMapColor = app.scanMapColor;
            S.scanOptimizeMode = app.scanOptimizeMode;
            S.scanOptimizePercentile = app.scanOptimizePercentile;
            S.scanManualCLim = app.scanManualCLim;
        end
        
        
        function applySessionState(app, S)
            
            names = fieldnames(S);
            
            for k = 1:numel(names)
                fn = names{k};
                
                if isprop(app, fn)
                    try
                        app.(fn) = S.(fn);
                    catch
                    end
                end
            end
            
            if ~isempty(app.mode)
                switch app.mode
                    case 1
                        app.safeSetComponentValue('DatamodesDropDown', 'Value', 'Single spectra');
                        app.safeSetComponentValue('ScantypeDropDown', 'Enable', 'off');
                        app.safeSetComponentValue('MapcolorDropDown', 'Enable', 'off');
                        
                    case 2
                        app.safeSetComponentValue('DatamodesDropDown', 'Value', 'Scan spectra');
                        app.safeSetComponentValue('ScantypeDropDown', 'Enable', 'on');
                        app.safeSetComponentValue('MapcolorDropDown', 'Enable', 'on');
                end
            end
            
            app.updateInfoField();
            
            if ~isempty(app.wn)
                app.refreshMainAxesAfterChild();
            end
            
            if ~isempty(app.compareGroups)
                app.updateGroupDataDropdown();
                app.updateSpectralTypeAvailability();
            end
            
            if ~isempty(app.singleQC) && isstruct(app.singleQC) && isfield(app.singleQC, 'Status')
                app.updateSingleQCDisplay();
            end
            
            if ~isempty(app.singleData) && isstruct(app.singleData) && isfield(app.singleData, 'SpectraCell')
                app.plotSingleHomeSpectra();
            end
            
            if ~isempty(app.pcaScores) && ~isempty(app.pcaLoadings)
                app.populatePCATrees();
                
                if size(app.pcaScores,2) >= 2
                    app.plotPCScorePair(app.currentPCX, app.currentPCY);
                end
                
                if ~isempty(app.currentLoadingPCs)
                    app.plotPCLoadings(app.currentLoadingPCs);
                elseif size(app.pcaLoadings,2) >= 1
                    app.plotPCLoadings(1);
                end
            end
            
            % Only run scan UI recovery if the current app really has scan UI components.
            if isprop(app, 'UIAxes9') && isprop(app, 'OptionsDropDown') && isprop(app, 'OptionsDropDown_2')
                if isprop(app, 'scanLoaded') && app.scanLoaded
                    app.initializeScanAnalysisUI();
                    
                    if isprop(app, 'SavedfeaturesDropDown')
                        app.updateSavedFeaturesDropdown();
                    end
                    
                    if ~isempty(app.scanCurrentAxis1Map)
                        app.plotScanMap(app.UIAxes8, ...
                            app.scanCurrentAxis1Map, ...
                            app.scanCurrentAxis1Title);
                    end
                    
                    if ~isempty(app.scanCurrentAxis2Map)
                        app.plotScanMap(app.UIAxes9, ...
                            app.scanCurrentAxis2Map, ...
                            app.scanCurrentAxis2Title);
                    elseif ~isempty(app.scanCurrentFeatureMap)
                        app.plotScanMap(app.UIAxes9, ...
                            app.scanCurrentFeatureMap, ...
                            app.scanCurrentFeatureName);
                    end
                end
            end
        end
        
        
        function saveCurrentSession(app)
            if isempty(app.projectFolder)
                choice = questdlg( ...
                    'No backup folder is active. Select a folder to save this session?', ...
                    'Save session', ...
                    'Select folder', ...
                    'Cancel', ...
                    'Select folder');
                if ~strcmp(choice, 'Select folder')
                    return
                end
                baseFolder = uigetdir('', 'Select project/output folder');
                if isequal(baseFolder, 0)
                    return
                end
                app.projectFolder = baseFolder;
            end
            outFolder = app.getProjectSubFolder("Session");
            if outFolder == ""
                return
            end
            S = app.collectSessionState();
            filePath = fullfile(outFolder, 'raman_session.mat');
            try
                save(filePath, 'S', '-v7.3');
                msgbox(sprintf('Session saved:\n%s', filePath), 'Save session');
            catch ME
                uialert(app.UIFigure, ME.message, 'Save session error');
            end
        end
        
        
        function ok = restoreSessionFromFile(app)
            ok = false;
            app.setupManageDataFolder();
            [file, path] = uigetfile( ...
                {'*.mat','MAT-files (*.mat)'}, ...
                'Select Raman session file', ...
                app.manageDataFolder);
            if isequal(file, 0)
                return
            end
            filePath = fullfile(path, file);
            try
                loaded = load(filePath, 'S');
                if ~isfield(loaded, 'S')
                    uialert(app.UIFigure, ...
                        'Selected MAT file does not contain Raman session variable S.', ...
                        'Invalid session file');
                    return
                end
                app.applySessionState(loaded.S);
                msgbox(sprintf('Session restored:\n%s', filePath), ...
                    'Restore session');
                ok = true;
            catch ME
                uialert(app.UIFigure, ME.message, 'Restore session error');
            end
        end
        
        
        function [ ...
                X, ...
                groupLabels, ...
                groupNames, ...
                spectrumNames, ...
                classWn] = ...
                buildClassificationInput(app)
            
            [ ...
                X, ...
                groupLabels, ...
                groupNames, ...
                spectrumNames] = ...
                app.buildPCAInputFromLabeledGroups();
            
            
            if isempty(X)
                classWn = [];
                return
            end
            
            
            fullWn = ...
                app.analysisWn(:);
            
            
            [ ...
                mask, ...
                ranges, ...
                rangeMode, ...
                ok] = ...
                app.askTemporaryWnMask( ...
                fullWn, ...
                'Classification');
            
            
            if ~ok
                
                X = [];
                classWn = [];
                
                return
            end
            
            
            X = ...
                X(:,mask);
            
            
            classWn = ...
                fullWn(mask);
            
            
            % =========================================================
            % Store only the run recipe.
            % =========================================================
            
            app.classRunRecipe = struct();
            
            app.classRunRecipe.RangeMode = ...
                rangeMode;
            
            app.classRunRecipe.WnRanges = ...
                ranges;
            
            app.classRunRecipe.WnPointCount = ...
                nnz(mask);
            
            app.classRunRecipe.CreatedAt = ...
                string(datetime( ...
                'now', ...
                'Format', ...
                'yyyy-MM-dd HH:mm:ss'));
            
        end


        function Xwork = normalizeClassificationMatrix(app, X, wn, normMethod)
            Xwork = X;
            
            switch string(normMethod)
                case "No normalization"
                    return
                    
                case {"Min-max","Max","Vector norm","Area","SNV"}
                    oldWn = app.analysisWn;
                    app.analysisWn = wn(:);
                    Xtmp = Xwork';
                    Xtmp = app.normalizeSpectra(Xtmp, normMethod);
                    Xwork = Xtmp';
                    app.analysisWn = oldWn;
                    
                case "Autoscale / Z-score"
                    mu = mean(Xwork,1,'omitnan');
                    sd = std(Xwork,0,1,'omitnan');
                    sd(sd == 0 | isnan(sd)) = 1;
                    Xwork = (Xwork - mu) ./ sd;
            end
        end
        
        
        function runPcaLdaClassification(app, X, groupLabels, groupNames, wn)
            groupLabels = string(groupLabels(:));
            groupNames = string(groupNames(:));
            
            nClass = numel(groupNames);
            maxPC = min([10, size(X,1)-nClass, size(X,2)]);
            
            if maxPC < 1
                error('PCA-LDA needs more spectra than number of classes.');
            end
            
            ansPC = inputdlg({'Number of PCs for LDA:'}, ...
                'PCA-LDA settings', [1 45], {num2str(min(5,maxPC))});
            
            if isempty(ansPC)
                error('PCA-LDA was cancelled.');
            end
            
            nPC = round(str2double(ansPC{1}));
            
            if isnan(nPC) || nPC < 1 || nPC > maxPC
                error('Number of PCs must be between 1 and %d.', maxPC);
            end
            
            [coeff, score] = pca(X, ...
                'Centered', true, ...
                'Rows', 'complete', ...
                'NumComponents', nPC);
            
            Z = score(:,1:nPC);
            
            Sw = zeros(nPC,nPC);
            Sb = zeros(nPC,nPC);
            muAll = mean(Z,1,'omitnan');
            
            for g = 1:nClass
                idx = groupLabels == groupNames(g);
                Zg = Z(idx,:);
                ng = size(Zg,1);
                
                if ng < 2
                    error('Each group should contain at least 2 spectra for LDA.');
                end
                
                muG = mean(Zg,1,'omitnan');
                Cg = cov(Zg);
                
                if any(~isfinite(Cg(:)))
                    Cg = zeros(nPC,nPC);
                end
                
                Sw = Sw + (ng-1) * Cg;
                
                dmu = (muG - muAll)';
                Sb = Sb + ng * (dmu * dmu');
            end
            
            A = pinv(Sw) * Sb;
            [V,D] = eig(A);
            
            eigVal = real(diag(D));
            [eigVal,ord] = sort(eigVal,'descend');
            V = real(V(:,ord));
            
            nLD = min(nClass-1, nPC);
            if nLD < 1
                nLD = 1;
            end
            
            Wlda = V(:,1:nLD);
            ldScore = Z * Wlda;
            spectralCoeff = coeff(:,1:nPC) * Wlda;
            
            eigUse = eigVal(1:nLD);
            eigUse(eigUse < 0) = 0;
            if sum(eigUse) > 0
                ldExplained = 100 * eigUse ./ sum(eigUse);
            else
                ldExplained = NaN(size(eigUse));
            end
            
            Mdl = fitcdiscr(Z, categorical(groupLabels), 'DiscrimType', 'linear');
            pred = string(resubPredict(Mdl));
            
            acc = mean(pred == groupLabels) * 100;
            
            app.classMethod = "PCA-LDA";
            app.classScores = ldScore;
            app.classLoadings = spectralCoeff;
            app.classExplained = ldExplained(:);
            app.classComponentNames = "LD" + string(1:nLD);
            app.classPredictedLabels = pred(:);
            app.classAccuracy = acc;
            app.classConfusion = app.makeConfusionTable(groupLabels, pred, groupNames);
        end
        
        
        function runPLSDAClassification(app, X, groupLabels, groupNames, wn)
            nClass = numel(groupNames);
            
            Y = zeros(numel(groupLabels), nClass);
            for g = 1:nClass
                Y(:,g) = double(groupLabels == groupNames(g));
            end
            
            maxLV = min([10, size(X,1)-1, size(X,2)]);
            
            if maxLV < 1
                error('PLS-DA needs at least 2 valid spectra.');
            end
            
            ansLV = inputdlg( ...
                {'Number of latent variables for PLS-DA:'}, ...
                'PLS-DA settings', ...
                [1 45], ...
                {num2str(min(3,maxLV))});
            
            if isempty(ansLV)
                error('PLS-DA was cancelled.');
            end
            
            nLV = round(str2double(ansLV{1}));
            
            if isnan(nLV) || nLV < 1 || nLV > maxLV
                error('Number of latent variables must be between 1 and %d.', maxLV);
            end
            
            [XL, ~, XS, ~, beta, PCTVAR] = plsregress(X, Y, nLV);
            
            Yhat = [ones(size(X,1),1), X] * beta;
            [~, idxPred] = max(Yhat, [], 2);
            pred = groupNames(idxPred);
            acc = mean(pred == groupLabels) * 100;
            
            app.classMethod = "PLS-DA";
            app.classScores = XS(:,1:nLV);
            app.classLoadings = XL(:,1:nLV);
            app.classExplained = 100 * PCTVAR(1,1:nLV);
            app.classComponentNames = "LV" + string(1:nLV);
            app.classPredictedLabels = pred(:);
            app.classAccuracy = acc;
            app.classConfusion = app.makeConfusionTable(groupLabels, pred, groupNames);
        end
        
        
        function C = makeConfusionTable(app, trueLabels, predLabels, groupNames)
            trueLabels = string(trueLabels(:));
            predLabels = string(predLabels(:));
            groupNames = string(groupNames(:));
            
            M = zeros(numel(groupNames));
            
            for i = 1:numel(groupNames)
                for j = 1:numel(groupNames)
                    M(i,j) = sum(trueLabels == groupNames(i) & predLabels == groupNames(j));
                end
            end
            
            C = array2table(M, ...
                'VariableNames', matlab.lang.makeValidName("Pred_" + groupNames), ...
                'RowNames', cellstr("True_" + groupNames));
        end
        
        function populateClassificationTrees(app)
            
            delete(app.Tree_2.Children);
            delete(app.Tree2_2.Children);
            
            
            if isempty(app.classScores)
                
                return
                
            end
            
            
            nComp = ...
                size(app.classScores,2);
            
            
            methodRaw = ...
                upper(strtrim(string(app.classMethod)));
            
            if contains(methodRaw,"SVM")
                
                method = "SVM";
                
            elseif contains(methodRaw,"LDA")
                
                method = "LDA";
                
            elseif contains(methodRaw,"PLS")
                
                method = "PLSDA";
                
            else
                
                method = methodRaw;
                
            end
            
            
            % =========================================================
            % SCORE / VISUALIZATION TREE
            % =========================================================
            
            rootScore = ...
                uitreenode(app.Tree_2);
            
            
            switch method
                
                case "SVM"
                    
                    rootScore.Text = ...
                        'PCA visualization of SVM input';
                    
                case "LDA"
                    
                    rootScore.Text = ...
                        'LDA discriminant score plots';
                    
                case "PLSDA"
                    
                    rootScore.Text = ...
                        'PLS-DA latent-variable score plots';
                    
                otherwise
                    
                    rootScore.Text = ...
                        char(method + " score plots");
                    
            end
            
            
            rootScore.NodeData = [];
            
            
            if nComp == 1
                
                node = ...
                    uitreenode(rootScore);
                
                node.Text = ...
                    char(app.classComponentNames(1));
                
                node.NodeData = ...
                    struct( ...
                    'Type',"score", ...
                    'CX',1, ...
                    'CY',1);
                
                
            else
                
                for i = 1:nComp-1
                    
                    for j = i+1:nComp
                        
                        node = ...
                            uitreenode(rootScore);
                        
                        
                        node.Text = ...
                            sprintf( ...
                            '%s vs %s', ...
                            app.classComponentNames(i), ...
                            app.classComponentNames(j));
                        
                        
                        node.NodeData = ...
                            struct( ...
                            'Type',"score", ...
                            'CX',i, ...
                            'CY',j);
                        
                    end
                    
                end
                
            end
            
            
            expand(rootScore);
            
            
            % =========================================================
            % LOADING / IMPORTANCE TREE
            % =========================================================
            
            rootLoading = ...
                uitreenode(app.Tree2_2);
            
            
            switch method
                
                case "SVM"
                    
                    rootLoading.Text = ...
                        'SVM spectral importance';
                    
                case "LDA"
                    
                    rootLoading.Text = ...
                        'LDA spectral coefficients';
                    
                case "PLSDA"
                    
                    rootLoading.Text = ...
                        'PLS-DA loadings';
                    
                otherwise
                    
                    rootLoading.Text = ...
                        char(method + " variable importance");
                    
            end
            
            
            rootLoading.NodeData = [];
            
            
            nLoad = ...
                size(app.classLoadings,2);
            
            
            for i = 1:nLoad
                
                node = ...
                    uitreenode(rootLoading);
                
                
                node.Text = ...
                    char(app.getClassLoadingName(i));
                
                
                node.NodeData = ...
                    struct( ...
                    'Type',"loading", ...
                    'Comp',i);
                
            end
            
            
            expand(rootLoading);
            
        end
        
        function plotClassScorePair(app, cx, cy)
            if isempty(app.classScores)
                return
            end
            
            nComp = size(app.classScores,2);
            cx = min(cx,nComp);
            cy = min(cy,nComp);
            
            app.currentClassScoreX = cx;
            app.currentClassScoreY = cy;
            
            cla(app.UIAxes4_2,'reset');
            cla(app.UIAxes6_3,'reset');
            
            hold(app.UIAxes4_2,'on');
            xline(app.UIAxes4_2,0,'--k','HandleVisibility','off');
            
            groupNames = unique(app.classGroupLabels,'stable');
            scoreSize = app.ScoresizeSlider_2.Value;
            
            if nComp == 1
                yval = zeros(size(app.classScores,1),1);
                
                for g = 1:numel(groupNames)
                    mask = app.classGroupLabels == groupNames(g);
                    scatter(app.UIAxes4_2, app.classScores(mask,cx), yval(mask), ...
                        scoreSize, 'filled', 'DisplayName', char(groupNames(g)));
                end
                
                ylabel(app.UIAxes4_2,'Class baseline');
            else
                yline(app.UIAxes4_2,0,'--k','HandleVisibility','off');
                
                for g = 1:numel(groupNames)
                    mask = app.classGroupLabels == groupNames(g);
                    scatter(app.UIAxes4_2, app.classScores(mask,cx), app.classScores(mask,cy), ...
                        scoreSize, 'filled', 'DisplayName', char(groupNames(g)));
                end
                
                ylabel(app.UIAxes4_2, app.getClassAxisLabel(cy));
            end
            
            hold(app.UIAxes4_2,'off');
            box(app.UIAxes4_2,'on');
            grid(app.UIAxes4_2,'off');
            
            xlabel(app.UIAxes4_2, app.getClassAxisLabel(cx));
            title(app.UIAxes4_2, sprintf('%s score plot | Accuracy %.2f%%', ...
                app.classMethod, app.classAccuracy));
            
            app.drawLegendInSmallAxis(app.UIAxes6_3, groupNames);
        end
        
        
        function label = getClassAxisLabel(app, comp)
            compName = app.classComponentNames(comp);
            
            if ~isempty(app.classExplained) && numel(app.classExplained) >= comp && isfinite(app.classExplained(comp))
                label = sprintf('%s (%.2f%%)', compName, app.classExplained(comp));
            else
                label = char(compName);
            end
        end
        
        
        function plotClassLoadings(app, comps)
            if isempty(app.classLoadings)
                return
            end
            
            comps = unique(comps(:)', 'stable');
            comps = comps(comps >= 1 & comps <= size(app.classLoadings,2));
            
            if isempty(comps)
                return
            end
            
            app.currentClassLoadings = comps;
            
            mask = app.getClassLoadingWnMask();
            wnPlot = app.classWn(mask);
            
            cla(app.UIAxes5_2,'reset');
            cla(app.UIAxes6_4,'reset');
            
            hold(app.UIAxes5_2,'on');
            
            L = app.classLoadings(mask, comps);
            
            if numel(comps) == 1
                offsets = 0;
            else
                yRange = max(L(:),[],'omitnan') - min(L(:),[],'omitnan');
                
                if yRange == 0 || isnan(yRange)
                    yRange = 1;
                end
                
                offsets = (0:numel(comps)-1) * 1.25 * yRange;
            end
            
            legendNames = strings(numel(comps),1);
            allY = [];
            
            for k = 1:numel(comps)
                c = comps(k);
                y = app.classLoadings(mask,c) + offsets(k);
                
                yline(app.UIAxes5_2, offsets(k), '--k', 'HandleVisibility','off');
                
                plot(app.UIAxes5_2, wnPlot, y, ...
                    'LineWidth', 1.8, ...
                    'DisplayName', char(app.getClassLoadingName(c)));
                
                legendNames(k) = app.getClassLoadingName(c);
                allY = [allY; y(:)];
            end
            
            hold(app.UIAxes5_2,'off');
            
            box(app.UIAxes5_2,'on');
            grid(app.UIAxes5_2,'off');
            
            xlabel(app.UIAxes5_2,'Wavenumber (cm^{-1})');
            
            if app.classMethod == "PCA-LDA"
                
                ylabel( ...
                    app.UIAxes5_2, ...
                    'Discriminant coefficient');
                
                title( ...
                    app.UIAxes5_2, ...
                    'PCA-LDA spectral coefficients');
                
                
            elseif startsWith(app.classMethod, "SVM")
                
                ylabel( ...
                    app.UIAxes5_2, ...
                    'Relative importance');
                
                title( ...
                    app.UIAxes5_2, ...
                    app.classMethod + " spectral importance", ...
                    'Interpreter', ...
                    'none');
                
                
            else
                
                ylabel( ...
                    app.UIAxes5_2, ...
                    'Loading intensity');
                
                title( ...
                    app.UIAxes5_2, ...
                    'PLS-DA loadings');
                
            end
            
            if ~isempty(wnPlot)
                xlim(app.UIAxes5_2,[min(wnPlot), max(wnPlot)]);
            end
            
            yl = [min(allY,[],'omitnan'), max(allY,[],'omitnan')];
            yr = diff(yl);
            
            if yr == 0 || isnan(yr)
                yr = 1;
            end
            
            ylim(app.UIAxes5_2,[yl(1)-0.2*yr, yl(2)+0.2*yr]);
            
            if app.HighpeaksCheckBox_2.Value && numel(comps) == 1
                app.highlightHighClassLoadingPeaks(comps(1), offsets(1));
            end
            
            app.drawLegendInSmallAxis(app.UIAxes6_4, legendNames);
        end
        
        
        function name = getClassLoadingName(app, comp)
            
            if app.classMethod == "PCA-LDA"
                
                name = ...
                    "LD" + string(comp) + ...
                    " coefficient";
                
                
            elseif startsWith(app.classMethod, "SVM")
                
                name = ...
                    "SVM spectral importance";
                
                
            else
                
                name = ...
                    "LV" + string(comp) + ...
                    " loading";
                
            end
            
        end
        
        
        function mask = getClassLoadingWnMask(app)
            wn = app.classWn(:);
            mask = true(size(wn));
            
            txt = strtrim(string(app.EditField_5.Value));
            
            if txt == ""
                return
            end
            
            nums = regexp(char(txt),'[-+]?\d*\.?\d+','match');
            nums = str2double(nums);
            
            if numel(nums) < 2 || any(isnan(nums(1:2)))
                uialert(app.UIFigure, ...
                    'Enter WN filter as: start end, for example: 800 1800', ...
                    'Invalid WN filter');
                return
            end
            
            lo = min(nums(1),nums(2));
            hi = max(nums(1),nums(2));
            
            newMask = wn >= lo & wn <= hi;
            
            if ~any(newMask)
                uialert(app.UIFigure, ...
                    'No loading points found in this WN range.', ...
                    'Invalid WN filter');
                return
            end
            
            mask = newMask;
        end
        
        
        function highlightHighClassLoadingPeaks(app, comp, offset)
            txt = strtrim(string(app.peakEditField_2.Value));
            
            if txt == ""
                pct = 10;
                app.peakEditField_2.Value = "10";
            else
                pct = str2double(txt);
            end
            
            if isnan(pct) || pct <= 0 || pct > 100
                uialert(app.UIFigure, ...
                    'Enter % peak from 0-100. Example: 10 means top 10% strongest loading peaks.', ...
                    'Invalid % peak');
                return
            end
            
            wn = app.classWn(:);
            y0 = app.classLoadings(:,comp);
            absY = abs(y0);
            
            thr = prctile(absY,100-pct);
            minProm = 0.05 * max(absY,[],'omitnan');
            
            [pksMax,locMax] = findpeaks(y0,'MinPeakHeight',thr,'MinPeakProminence',minProm);
            [pksMin,locMin] = findpeaks(-y0,'MinPeakHeight',thr,'MinPeakProminence',minProm);
            
            locs = [locMax(:); locMin(:)];
            amps = [abs(pksMax(:)); abs(pksMin(:))];
            
            if isempty(locs)
                return
            end
            
            [~,ord] = sort(amps,'descend');
            maxN = min(12,numel(ord));
            locs = locs(ord(1:maxN));
            
            yl = ylim(app.UIAxes5_2);
            
            dx = median(abs(diff(wn)),'omitnan');
            
            if isnan(dx) || dx <= 0
                dx = (max(wn)-min(wn))/numel(wn);
            end
            
            bandHalfWidth = 4 * dx;
            
            for i = 1:numel(locs)
                x0 = wn(locs(i));
                
                patch(app.UIAxes5_2, ...
                    [x0-bandHalfWidth x0+bandHalfWidth x0+bandHalfWidth x0-bandHalfWidth], ...
                    [yl(1) yl(1) yl(2) yl(2)], ...
                    [0.5 0.5 0.5], ...
                    'FaceAlpha',0.18, ...
                    'EdgeColor','none', ...
                    'HandleVisibility','off');
            end
        end
        
        
        function [previewSpectra, pixelIdx] = getScanSpectraPreview(app, maxPlot)
            if nargin < 2 || isempty(maxPlot)
                maxPlot = 300;
            end
            
            if isempty(app.cube)
                previewSpectra = [];
                pixelIdx = [];
                return
            end
            
            ny = size(app.cube, 1);
            nx = size(app.cube, 2);
            nWn = size(app.cube, 3);
            nPix = ny * nx;
            
            nPlot = min(maxPlot, nPix);
            pixelIdx = unique(round(linspace(1, nPix, nPlot)), 'stable');
            nPlot = numel(pixelIdx);
            
            previewSpectra = zeros(nWn, nPlot, 'like', app.cube);
            
            for i = 1:nPlot
                [yy, xx] = ind2sub([ny, nx], pixelIdx(i));
                previewSpectra(:, i) = reshape(app.cube(yy, xx, :), [], 1);
            end
        end


        function tf = scanOnlyGuard(app)
            tf = false;
            
            if isempty(app.mode) || app.mode ~= 2
                msgbox('This module is only for scan spectra.', ...
                    'Scan analysis');
                return
            end
            
            if isempty(app.wn) || isempty(app.cube)
                msgbox('No scan data found. Please load scan data in Preprocess first.', ...
                    'Scan analysis');
                return
            end
            
            tf = true;
        end
        
        
        function initializeScanAnalysisUI(app)
            items = { ...
                '', ...
                'Raw scan band map', ...
                'Preprocessed scan band map', ...
                'Raw spectra preview', ...
                'Preprocessed spectra preview', ...
                'Raw mean spectra', ...
                'Preprocessed mean spectra', ...
                'Current ratio / feature map'};
            
            app.OptionsDropDown.Items = items;
            app.OptionsDropDown_2.Items = items;
            
            app.OptionsDropDown.Value = 'Raw scan band map';
            app.OptionsDropDown_2.Value = 'Preprocessed scan band map';
            if app.scanBandDisplayText == ""
                app.scanBandDisplayText = sprintf('%.0f %.0f', ...
                    app.scanBandLow, app.scanBandHigh);
            end
            
            app.ReportCustomTitleEditField.Value = char(app.scanBandDisplayText);
            if isprop(app, 'MapcolorDropDown_2') && isvalid(app.MapcolorDropDown_2)
                app.MapcolorDropDown_2.Items = {'', 'jet', 'hot', 'turbo', 'parula', 'bone', 'gray'};
                if app.scanMapColor == ""
                    app.scanMapColor = "turbo";
                end
                app.MapcolorDropDown_2.Value = char(app.scanMapColor);
            end
            
            if isempty(app.SavedfeaturesDropDown.Items) || ...
                    numel(app.SavedfeaturesDropDown.Items) == 1
                app.SavedfeaturesDropDown.Items = {''};
                app.SavedfeaturesDropDown.Value = '';
            end
        end
        
        
        function loadScanDataFromPreprocess(app)
            if ~app.scanOnlyGuard()
                return
            end
            
            app.scanWn = app.wn(:);
            
            if ~isempty(app.rawCube)
                app.scanRawCube = app.rawCube;
            else
                app.scanRawCube = app.cube;
            end
            
            app.scanProcessedCube = app.cube;
            
            app.scanLoaded = true;
            
            app.initializeScanAnalysisUI();
            
            app.plotScanOption(app.UIAxes8, app.OptionsDropDown.Value, 1);
            app.plotScanOption(app.UIAxes9, app.OptionsDropDown_2.Value, 2);
            
            msgbox(sprintf([ ...
                'Scan data loaded into Scan analysis.\n\n' ...
                'Cube size: %d x %d x %d\n' ...
                'Total spectra: %d\n' ...
                'Default band: %.0f - %.0f cm^{-1}'], ...
                size(app.scanProcessedCube,1), ...
                size(app.scanProcessedCube,2), ...
                size(app.scanProcessedCube,3), ...
                size(app.scanProcessedCube,1) * size(app.scanProcessedCube,2), ...
                app.scanBandLow, app.scanBandHigh), ...
                'Scan analysis');
        end
        
        
        function [cubeData, sourceText] = getScanCubeByOption(app, optionText)
            optionText = string(optionText);
            
            switch optionText
                case {"Raw scan band map", "Raw spectra preview", "Raw mean spectra"}
                    cubeData = app.scanRawCube;
                    sourceText = "Raw scan";
                    
                otherwise
                    cubeData = app.scanProcessedCube;
                    sourceText = "Preprocessed scan";
            end
        end
        
        
        function map2D = extractScanBandMap(app, cubeData, wnLow, wnHigh)
            map2D = [];
            
            if isempty(cubeData) || isempty(app.scanWn)
                return
            end
            
            lo = min(wnLow, wnHigh);
            hi = max(wnLow, wnHigh);
            
            idx = app.scanWn >= lo & app.scanWn <= hi;
            
            if ~any(idx)
                [~, midIdx] = min(abs(app.scanWn - mean([lo hi])));
                map2D = cubeData(:,:,midIdx);
                return
            end
            
            map2D = mean(cubeData(:,:,idx), 3, 'omitnan');
        end
        
        
        function plotScanMap(app, ax, map2D, titleText)
            cla(ax, 'reset');
            
            if isempty(map2D)
                title(ax, 'No map');
                return
            end
            
            img = imagesc(ax, map2D);
            
            if isprop(img, 'Interpolation')
                img.Interpolation = 'nearest';
            end
            
            axis(ax, 'image');
            axis(ax, 'tight');
            
            ax.XTickMode = 'auto';
            ax.YTickMode = 'auto';
            ax.Box = 'on';
            
            xlabel(ax, 'X pixel');
            ylabel(ax, 'Y pixel');
            title(ax, titleText, 'Interpreter', 'none');
            
            app.applyScanMapColor(ax);
            app.applyScanContrast(ax, map2D);
            
            colorbar(ax);
            grid(ax, 'off');
        end
        
        
        function [previewSpectra, pixelIdx] = getScanSpectraPreviewFromCube(app, cubeData, maxPlot)
            if nargin < 3 || isempty(maxPlot)
                maxPlot = 300;
            end
            
            previewSpectra = [];
            pixelIdx = [];
            
            if isempty(cubeData)
                return
            end
            
            ny = size(cubeData, 1);
            nx = size(cubeData, 2);
            nWn = size(cubeData, 3);
            nPix = ny * nx;
            
            nPlot = min(maxPlot, nPix);
            pixelIdx = unique(round(linspace(1, nPix, nPlot)), 'stable');
            nPlot = numel(pixelIdx);
            
            previewSpectra = zeros(nWn, nPlot, 'like', cubeData);
            
            for i = 1:nPlot
                [yy, xx] = ind2sub([ny, nx], pixelIdx(i));
                previewSpectra(:, i) = reshape(cubeData(yy, xx, :), [], 1);
            end
        end
        
        
        function plotScanSpectraPreview(app, ax, cubeData, titleText)
            cla(ax, 'reset');
            
            [previewSpectra, pixelIdx] = app.getScanSpectraPreviewFromCube(cubeData, 300);
            
            if isempty(previewSpectra)
                title(ax, 'No spectra preview');
                return
            end
            
            plot(ax, app.scanWn, previewSpectra, 'LineWidth', 0.4);
            
            xlabel(ax, 'Wavenumber (cm^{-1})');
            ylabel(ax, 'Intensity');
            title(ax, sprintf('%s: %d preview spectra', ...
                titleText, numel(pixelIdx)), ...
                'Interpreter', 'none');
            
            box(ax, 'on');
            grid(ax, 'off');
            legend(ax, 'off');
            
            if ~isempty(app.spectralDef) && isfield(app.spectralDef, 'roiMask') && ...
                    numel(app.spectralDef.roiMask) == numel(app.scanWn) && any(app.spectralDef.roiMask)
                x = app.scanWn(app.spectralDef.roiMask);
                xlim(ax, [min(x), max(x)]);
            else
                xlim(ax, [min(app.scanWn), max(app.scanWn)]);
            end
        end
        
        
        function plotScanMeanSpectra(app, ax, cubeData, titleText)
            cla(ax, 'reset');
            
            if isempty(cubeData)
                title(ax, 'No mean spectra');
                return
            end
            
            % cubeData: Y x X x WN
            ny = size(cubeData, 1);
            nx = size(cubeData, 2);
            nWn = size(cubeData, 3);
            
            meanSp = zeros(nWn, 1);
            sdSp = zeros(nWn, 1);
            
            % Tính theo từng wavenumber để tránh tạo matrix khổng lồ
            for k = 1:nWn
                plane = cubeData(:,:,k);
                vals = plane(:);
                meanSp(k) = mean(vals, 'omitnan');
                sdSp(k) = std(vals, 0, 'omitnan');
            end
            
            upper = meanSp + sdSp;
            lower = meanSp - sdSp;
            
            hold(ax, 'on');
            
            fill(ax, ...
                [app.scanWn; flipud(app.scanWn)], ...
                [upper; flipud(lower)], ...
                [0.8 0.8 0.8], ...
                'FaceAlpha', 0.25, ...
                'EdgeColor', 'none', ...
                'HandleVisibility', 'off');
            
            plot(ax, app.scanWn, meanSp, ...
                'LineWidth', 1.5, ...
                'DisplayName', char(titleText));
            
            hold(ax, 'off');
            
            xlabel(ax, 'Wavenumber (cm^{-1})');
            ylabel(ax, 'Intensity');
            title(ax, titleText, 'Interpreter', 'none');
            
            box(ax, 'on');
            grid(ax, 'off');
            legend(ax, 'off');
            
            if ~isempty(app.spectralDef) && isfield(app.spectralDef, 'roiMask') && ...
                    numel(app.spectralDef.roiMask) == numel(app.scanWn) && any(app.spectralDef.roiMask)
                x = app.scanWn(app.spectralDef.roiMask);
                xlim(ax, [min(x), max(x)]);
            else
                xlim(ax, [min(app.scanWn), max(app.scanWn)]);
            end
        end
        
        function plotScanOption(app, ax, optionText, axisID)
            if ~app.scanOnlyGuard()
                return
            end
            
            if ~app.scanLoaded
                app.loadScanDataFromPreprocess();
                return
            end
            
            optionText = string(optionText);
            
            if optionText == ""
                cla(ax, 'reset');
                title(ax, 'Select display option');
                return
            end
            
            [cubeData, sourceText] = app.getScanCubeByOption(optionText);
            
            switch optionText
                case {"Raw scan band map", "Preprocessed scan band map"}
                    map2D = app.extractCurrentScanBandMap(cubeData);
                    
                    titleText = app.makeCurrentScanBandTitle(sourceText);
                    
                    app.plotScanMap(ax, map2D, titleText);
                    
                    % Khi user đang xem band map, tránh Save features bị dính ratio map cũ.
                    app.scanCurrentFeatureMap = [];
                    app.scanCurrentFeatureName = "";
                    app.scanCurrentFeatureType = "";
                    app.scanCurrentFeatureFormula = "";
                    
                    if axisID == 1
                        app.scanCurrentAxis1Map = map2D;
                        app.scanCurrentAxis1Title = string(titleText);
                    else
                        app.scanCurrentAxis2Map = map2D;
                        app.scanCurrentAxis2Title = string(titleText);
                    end
                    
                case {"Raw spectra preview", "Preprocessed spectra preview"}
                    app.plotScanSpectraPreview(ax, cubeData, sourceText);
                    
                    if axisID == 1
                        app.scanCurrentAxis1Map = [];
                        app.scanCurrentAxis1Title = sourceText + " spectra preview";
                    else
                        app.scanCurrentAxis2Map = [];
                        app.scanCurrentAxis2Title = sourceText + " spectra preview";
                    end
                    
                case {"Raw mean spectra", "Preprocessed mean spectra"}
                    app.plotScanMeanSpectra(ax, cubeData, sourceText + " mean spectra");
                    
                    if axisID == 1
                        app.scanCurrentAxis1Map = [];
                        app.scanCurrentAxis1Title = sourceText + " mean spectra";
                    else
                        app.scanCurrentAxis2Map = [];
                        app.scanCurrentAxis2Title = sourceText + " mean spectra";
                    end
                    
                case "Current ratio / feature map"
                    if isempty(app.scanCurrentFeatureMap)
                        uialert(app.UIFigure, ...
                            'No current feature map. Please run Ratio Scan or select a saved feature first.', ...
                            'No feature map');
                        return
                    end
                    
                    app.plotScanMap(ax, app.scanCurrentFeatureMap, ...
                        app.scanCurrentFeatureName);
                    
                    if axisID == 1
                        app.scanCurrentAxis1Map = app.scanCurrentFeatureMap;
                        app.scanCurrentAxis1Title = app.scanCurrentFeatureName;
                    else
                        app.scanCurrentAxis2Map = app.scanCurrentFeatureMap;
                        app.scanCurrentAxis2Title = app.scanCurrentFeatureName;
                    end
            end
        end
        
        
        function ok = updateScanBandFromEditField(app)
            ok = false;
            
            txt = strtrim(string(app.ReportCustomTitleEditField.Value));
            
            [bandRanges, combineMode, displayText, okParse] = app.parseScanBandInput(txt);
            
            if ~okParse
                return
            end
            
            app.scanBandRanges = bandRanges;
            app.scanBandCombineMode = combineMode;
            app.scanBandDisplayText = displayText;
            
            app.scanBandLow = bandRanges(1,1);
            app.scanBandHigh = bandRanges(1,2);
            
            app.ReportCustomTitleEditField.Value = char(displayText);
            
            ok = true;
        end
        
        function map2D = computeBandFeatureMap(app, cubeData, wnLow, wnHigh, featureMode)
            map2D = [];
            
            if isempty(cubeData) || isempty(app.scanWn)
                return
            end
            
            if nargin < 5 || strlength(string(featureMode)) == 0
                featureMode = "Mean";
            end
            
            lo = min(wnLow, wnHigh);
            hi = max(wnLow, wnHigh);
            
            idx = app.scanWn >= lo & app.scanWn <= hi;
            
            if ~any(idx)
                error('No wavenumber points found in %.2f - %.2f cm^{-1}.', lo, hi);
            end
            
            switch string(featureMode)
                case "Area"
                    wnUse = app.scanWn(idx);
                    dataUse = cubeData(:,:,idx);
                    map2D = trapz(wnUse, dataUse, 3);
                    
                otherwise
                    map2D = mean(cubeData(:,:,idx), 3, 'omitnan');
            end
        end
        
        
        function bandRanges = askRatioBandsDirect(app, vars)
            n = numel(vars);
            prompt = cell(n,1);
            defaults = cell(n,1);
            
            for i = 1:n
                prompt{i} = sprintf('Band range for %s, e.g. 2840 2940:', vars(i));
                defaults{i} = sprintf('%.0f %.0f', app.scanBandLow, app.scanBandHigh);
            end
            
            ansBands = inputdlg(prompt, ...
                'Ratio bands', ...
                [1 60], ...
                defaults);
            
            if isempty(ansBands)
                bandRanges = [];
                return
            end
            
            bandRanges = zeros(n,2);
            
            for i = 1:n
                nums = regexp(ansBands{i}, '[-+]?\d*\.?\d+', 'match');
                nums = str2double(nums);
                
                if numel(nums) < 2 || any(isnan(nums(1:2)))
                    error('Invalid band range for %s.', vars(i));
                end
                
                bandRanges(i,:) = [min(nums(1), nums(2)), max(nums(1), nums(2))];
            end
        end
        
        
        function bandRanges = askRatioBandsFromFigure(app, cubeData, vars)
            n = numel(vars);
            bandRanges = zeros(n,2);
            
            nWn = size(cubeData, 3);
            meanSp = zeros(nWn, 1);
            
            for k = 1:nWn
                plane = cubeData(:,:,k);
                meanSp(k) = mean(plane(:), 'omitnan');
            end
            
            f = figure( ...
                'Name', 'Select ratio bands from mean spectra', ...
                'NumberTitle', 'off');
            
            ax = axes(f);
            plot(ax, app.scanWn, meanSp, 'LineWidth', 1.4);
            xlabel(ax, 'Wavenumber (cm^{-1})');
            ylabel(ax, 'Mean intensity');
            title(ax, 'Click 2 points for each band');
            box(ax, 'on');
            grid(ax, 'off');
            
            for i = 1:n
                uiwait(msgbox(sprintf( ...
                    'Select two points for band %s on the mean spectrum figure.', ...
                    vars(i)), ...
                    'Select band'));
                
                figure(f);
                [x, ~] = ginput(2);
                
                if numel(x) < 2
                    if isvalid(f)
                        close(f);
                    end
                    error('Band selection was cancelled.');
                end
                
                lo = min(x);
                hi = max(x);
                
                bandRanges(i,:) = [lo hi];
                
                hold(ax, 'on');
                yl = ylim(ax);
                patch(ax, ...
                    [lo hi hi lo], ...
                    [yl(1) yl(1) yl(2) yl(2)], ...
                    [0.7 0.7 0.7], ...
                    'FaceAlpha', 0.18, ...
                    'EdgeColor', 'none');
                
                text(ax, mean([lo hi]), yl(2), char(vars(i)), ...
                    'HorizontalAlignment', 'center', ...
                    'VerticalAlignment', 'top', ...
                    'FontWeight', 'bold');
                
                hold(ax, 'off');
            end
        end
        
        
        function resultMap = evaluateRatioFormulaMap(app, formulaText, vars, bandMaps)
            formulaText = string(formulaText);
            formulaChar = char(formulaText);
            % Chỉ cho phép ký tự an toàn
            if isempty(regexp(formulaChar, '^[A-Za-z0-9_\+\-\*\/\.\^\(\)\s]+$', 'once'))
                error('Formula contains invalid characters.');
            end
            % Chuyển operator sang element-wise cho map 2D
            expr = formulaChar;
            % Đổi * thành .*, nhưng không đổi nếu đã là .*
            expr = regexprep(expr, '(?<!\.)\*', '.*');
            % Đổi / thành ./, nhưng không đổi nếu đã là ./
            expr = regexprep(expr, '(?<!\.)/', './');
            % Đổi ^ thành .^, nhưng không đổi nếu đã là .^
            expr = regexprep(expr, '(?<!\.)\^', '.^');
            % Thay a,b,c... bằng bandMaps{i}
            for i = 1:numel(vars)
                v = char(vars(i));
                expr = regexprep(expr, ...
                    ['(?<![A-Za-z0-9_])' v '(?![A-Za-z0-9_])'], ...
                    sprintf('bandMaps{%d}', i));
            end
            resultMap = eval(expr);
            if ~isequal(size(resultMap), size(bandMaps{1}))
                error('Formula result size is not a valid scan map.');
            end
            resultMap(~isfinite(resultMap)) = NaN;
        end
        
        function updateSavedFeaturesDropdown(app)
            if isempty(app.scanFeatures)
                app.SavedfeaturesDropDown.Items = {''};
                app.SavedfeaturesDropDown.Value = '';
                return
            end
            
            names = string({app.scanFeatures.Name});
            app.SavedfeaturesDropDown.Items = cellstr([""; names(:)]);
            
            if app.SavedfeaturesDropDown.Value == ""
                app.SavedfeaturesDropDown.Value = char(names(end));
            end
        end
        
        
        function rgb = makeMultiFeatureRGB(app, featureMaps)
            nF = numel(featureMaps);
            
            baseSize = size(featureMaps{1});
            rgb = zeros([baseSize, 3]);
            
            colorList = [ ...
                1 0 0; ...
                0 1 0; ...
                0 0 1; ...
                1 1 0; ...
                1 0 1; ...
                0 1 1];
            
            for i = 1:nF
                M = featureMaps{i};
                
                if ~isequal(size(M), baseSize)
                    error('All selected feature maps must have the same size.');
                end
                
                M = double(M);
                lo = prctile(M(:), 1);
                hi = prctile(M(:), 99);
                
                if ~isfinite(lo) || ~isfinite(hi) || hi <= lo
                    Mn = zeros(size(M));
                else
                    Mn = (M - lo) ./ (hi - lo);
                    Mn(Mn < 0) = 0;
                    Mn(Mn > 1) = 1;
                end
                
                c = colorList(mod(i-1, size(colorList,1)) + 1, :);
                
                for ch = 1:3
                    rgb(:,:,ch) = rgb(:,:,ch) + Mn * c(ch);
                end
            end
            
            rgb(rgb > 1) = 1;
        end
        
        
        function plotMultiFeatureRGB(app, ax, rgbImg, selectedNames)
            cla(ax, 'reset');
            
            image(ax, rgbImg);
            axis(ax, 'image');
            axis(ax, 'tight');
            
            ax.XTickMode = 'auto';
            ax.YTickMode = 'auto';
            ax.Box = 'on';
            
            xlabel(ax, 'X pixel');
            ylabel(ax, 'Y pixel');
            
            title(ax, sprintf('Multi-feature RGB: %s', ...
                strjoin(string(selectedNames), ', ')), ...
                'Interpreter', 'none');
            
            grid(ax, 'off');
        end
        
        
        function refreshScanAnalysisAfterPreprocess(app)
            if isempty(app.mode) || app.mode ~= 2
                return
            end
            
            if ~app.scanLoaded
                return
            end
            
            app.scanWn = app.wn(:);
            
            if ~isempty(app.rawCube)
                app.scanRawCube = app.rawCube;
            end
            
            app.scanProcessedCube = app.cube;
            
            if isvalid(app.UIAxes8) && isvalid(app.UIAxes9)
                opt1 = string(app.OptionsDropDown.Value);
                opt2 = string(app.OptionsDropDown_2.Value);
                
                if opt1 ~= ""
                    app.plotScanOption(app.UIAxes8, opt1, 1);
                end
                
                if opt2 ~= ""
                    app.plotScanOption(app.UIAxes9, opt2, 2);
                end
            end
        end


        function [bandRanges, combineMode, displayText, ok] = parseScanBandInput(app, txt)
            ok = false;
            bandRanges = [];
            combineMode = "Average";
            displayText = "";
            
            txt = strtrim(string(txt));
            
            if txt == ""
                uialert(app.UIFigure, ...
                    ['Enter band as:' newline ...
                    '526' newline ...
                    'or 526 545' newline ...
                    'or 526 545; 852 864; 555 666'], ...
                    'Invalid band');
                return
            end
            
            parts = split(txt, ';');
            parts = strtrim(parts);
            parts = parts(parts ~= "");
            
            if isempty(parts)
                uialert(app.UIFigure, ...
                    'No valid band input found.', ...
                    'Invalid band');
                return
            end
            
            bandRanges = zeros(numel(parts), 2);
            labels = strings(numel(parts), 1);
            
            for i = 1:numel(parts)
                nums = regexp(char(parts(i)), '[-+]?\d*\.?\d+', 'match');
                nums = str2double(nums);
                
                nums = nums(~isnan(nums));
                
                if isempty(nums)
                    uialert(app.UIFigure, ...
                        sprintf('Invalid band segment: %s', parts(i)), ...
                        'Invalid band');
                    return
                end
                
                if numel(nums) == 1
                    centerWn = nums(1);
                    lo = centerWn - 5;
                    hi = centerWn + 5;
                    labels(i) = sprintf('%.2f ± 5 cm^{-1}', centerWn);
                    
                else
                    lo = min(nums(1), nums(2));
                    hi = max(nums(1), nums(2));
                    labels(i) = sprintf('%.2f - %.2f cm^{-1}', lo, hi);
                end
                
                if hi <= lo
                    uialert(app.UIFigure, ...
                        sprintf('Invalid band range: %s', parts(i)), ...
                        'Invalid band');
                    return
                end
                
                if ~isempty(app.scanWn)
                    wnMin = min(app.scanWn);
                    wnMax = max(app.scanWn);
                    
                    if hi < wnMin || lo > wnMax
                        uialert(app.UIFigure, ...
                            sprintf('Band %.2f - %.2f cm^{-1} is outside current wavenumber range.', lo, hi), ...
                            'Invalid band');
                        return
                    end
                end
                
                bandRanges(i,:) = [lo hi];
            end
            
            if size(bandRanges, 1) > 1
                choice = questdlg( ...
                    ['Multiple bands detected:' newline newline ...
                    strjoin(labels, newline) newline newline ...
                    'How do you want to combine them?'], ...
                    'Combine multiple bands', ...
                    'Average', ...
                    'Accumulate', ...
                    'Cancel', ...
                    'Average');
                
                if isempty(choice) || strcmp(choice, 'Cancel')
                    bandRanges = [];
                    return
                end
                
                combineMode = string(choice);
            else
                combineMode = "Average";
            end
            
            displayText = strjoin(labels, '; ');
            
            ok = true;
        end
        
        
        function map2D = extractBandMapFromRanges(app, cubeData, bandRanges, combineMode)
            map2D = [];
            
            if isempty(cubeData) || isempty(app.scanWn) || isempty(bandRanges)
                return
            end
            
            combineMode = string(combineMode);
            
            nBands = size(bandRanges, 1);
            bandMaps = cell(nBands, 1);
            
            for i = 1:nBands
                lo = min(bandRanges(i,1), bandRanges(i,2));
                hi = max(bandRanges(i,1), bandRanges(i,2));
                
                idx = app.scanWn >= lo & app.scanWn <= hi;
                
                if ~any(idx)
                    [~, midIdx] = min(abs(app.scanWn - mean([lo hi])));
                    bandMaps{i} = cubeData(:,:,midIdx);
                else
                    bandMaps{i} = mean(cubeData(:,:,idx), 3, 'omitnan');
                end
            end
            
            stack = cat(3, bandMaps{:});
            
            switch combineMode
                case "Accumulate"
                    map2D = sum(stack, 3, 'omitnan');
                    
                otherwise
                    map2D = mean(stack, 3, 'omitnan');
            end
        end
        
        
        function map2D = extractCurrentScanBandMap(app, cubeData)
            if isempty(app.scanBandRanges)
                app.scanBandRanges = [app.scanBandLow app.scanBandHigh];
            end
            
            map2D = app.extractBandMapFromRanges( ...
                cubeData, ...
                app.scanBandRanges, ...
                app.scanBandCombineMode);
        end
        
        
        function titleText = makeCurrentScanBandTitle(app, sourceText)
            if isempty(app.scanBandRanges)
                app.scanBandRanges = [app.scanBandLow app.scanBandHigh];
            end
            
            if size(app.scanBandRanges, 1) == 1
                titleText = sprintf('%s | %.2f - %.2f cm^{-1}', ...
                    sourceText, ...
                    app.scanBandRanges(1,1), ...
                    app.scanBandRanges(1,2));
            else
                titleText = sprintf('%s | %d bands | %s', ...
                    sourceText, ...
                    size(app.scanBandRanges, 1), ...
                    app.scanBandCombineMode);
            end
        end
        
        
        function applyScanMapColor(app, ax)
            colorName = "turbo";
            
            if isprop(app, 'MapcolorDropDown_2') && isvalid(app.MapcolorDropDown_2)
                if string(app.MapcolorDropDown_2.Value) ~= ""
                    colorName = string(app.MapcolorDropDown_2.Value);
                elseif app.scanMapColor ~= ""
                    colorName = app.scanMapColor;
                end
            elseif app.scanMapColor ~= ""
                colorName = app.scanMapColor;
            end
            
            app.scanMapColor = colorName;
            
            switch lower(char(colorName))
                case 'jet'
                    colormap(ax, jet);
                case 'hot'
                    colormap(ax, hot);
                case 'turbo'
                    colormap(ax, turbo);
                case 'parula'
                    colormap(ax, parula);
                case 'bone'
                    colormap(ax, bone);
                case 'gray'
                    colormap(ax, gray);
                otherwise
                    colormap(ax, turbo);
            end
        end
        
        function applyScanContrast(app, ax, map2D)
            vals = double(map2D(:));
            vals = vals(isfinite(vals));
            
            if isempty(vals)
                return
            end
            
            modeText = string(app.scanOptimizeMode);
            
            switch modeText
                case "Percentile"
                    pLow = app.scanOptimizePercentile(1);
                    pHigh = app.scanOptimizePercentile(2);
                    
                    lo = prctile(vals, pLow);
                    hi = prctile(vals, pHigh);
                    
                case "Manual"
                    lo = app.scanManualCLim(1);
                    hi = app.scanManualCLim(2);
                    
                otherwise
                    lo = min(vals);
                    hi = max(vals);
            end
            
            if isfinite(lo) && isfinite(hi) && hi > lo
                caxis(ax, [lo hi]);
            else
                lo = min(vals);
                hi = max(vals);
                
                if isfinite(lo) && isfinite(hi) && hi > lo
                    caxis(ax, [lo hi]);
                end
            end
        end
        
        function optimizeScanAxisDisplay(app, ax, axisID)
            if axisID == 1
                map2D = app.scanCurrentAxis1Map;
                titleText = app.scanCurrentAxis1Title;
            else
                map2D = app.scanCurrentAxis2Map;
                titleText = app.scanCurrentAxis2Title;
            end
            
            if isempty(map2D)
                % Nếu axis hiện tại không phải map, thử plot lại theo dropdown.
                if axisID == 1
                    opt = string(app.OptionsDropDown.Value);
                    if opt == "Raw scan band map" || opt == "Preprocessed scan band map" || opt == "Current ratio / feature map"
                        app.plotScanOption(app.UIAxes8, opt, 1);
                    end
                else
                    opt = string(app.OptionsDropDown_2.Value);
                    if opt == "Raw scan band map" || opt == "Preprocessed scan band map" || opt == "Current ratio / feature map"
                        app.plotScanOption(app.UIAxes9, opt, 2);
                    end
                end
                return
            end
            
            app.plotScanMap(ax, map2D, titleText);
        end
        
        

        function ok = showMetadataCorrectionWarning(app)
            
            ok = false;
            
            msg = sprintf([ ...
                'METADATA / LABEL CORRECTION WARNING\n\n' ...
                'This correction changes how spectra are grouped for analysis.\n\n' ...
                'Standard label rule:\n' ...
                '- Use short, unique, readable group IDs.\n' ...
                '- Use only letters, numbers, and underscore "_".\n' ...
                '- Do not use spaces, accents, slashes, dots, commas, or special symbols.\n' ...
                '- Do not start with a number.\n\n' ...
                'Good examples:\n' ...
                'A_control\n' ...
                'A_infected\n' ...
                'B_control\n' ...
                'B_infected\n' ...
                'Nipah_A_Control\n\n' ...
                'Bad examples:\n' ...
                'A control\n' ...
                'A-control\n' ...
                'A/control\n' ...
                '1 infected group\n' ...
                'Nhóm A nhiễm\n\n' ...
                'Why this matters:\n' ...
                '- Group labels are used later for mean spectra, PCA, classification, and export tables.\n' ...
                '- Wrong labels can merge unrelated spectra or split one biological group into multiple groups.\n' ...
                '- This step does NOT change the raw spectrum intensity data.\n\n' ...
                'Continue metadata / label correction?']);
            
            choice = questdlg( ...
                msg, ...
                'Metadata correction warning', ...
                'Continue correction', ...
                'Cancel', ...
                'Cancel');
            
            ok = strcmp(choice, 'Continue correction');
        end
        
        
        function ok = showWNCorrectionWarning(app)
            
            ok = false;
            
            qc = app.singleQC;
            
            mismatchCount = NaN;
            duplicateCount = NaN;
            nonMonoCount = NaN;
            linearCount = 0;
            nonlinearCount = 0;
            lengthCount = 0;
            
            if isfield(qc, 'WnLinearShiftCount')
                linearCount = qc.WnLinearShiftCount;
            end
            
            if isfield(qc, 'WnNonLinearShiftCount')
                nonlinearCount = qc.WnNonLinearShiftCount;
            end
            
            if isfield(qc, 'WnLengthMismatchCount')
                lengthCount = qc.WnLengthMismatchCount;
            end
            
            if isfield(qc, 'WnMismatchCount')
                mismatchCount = qc.WnMismatchCount;
            end
            
            if isfield(qc, 'WnDuplicateCount')
                duplicateCount = qc.WnDuplicateCount;
            end
            
            if isfield(qc, 'WnNonMonotonicCount')
                nonMonoCount = qc.WnNonMonotonicCount;
            end
            
            msg = sprintf([ ...
                'WN INTERPOLATION CORRECTION WARNING\n\n' ...
                'This correction forces all spectra onto one common wavenumber grid.\n\n' ...
                'Detected WN issues:\n' ...
                '- WN mismatch spectra: %d\n' ...
                '- Duplicated WN spectra: %d\n' ...
                '- Non-monotonic WN spectra: %d\n\n' ...
                'What this correction does:\n' ...
                '- Keeps the original intensity pattern as much as possible.\n' ...
                '- Interpolates each spectrum onto a shared reference WN axis.\n' ...
                '- Removes duplicated WN points before interpolation.\n' ...
                '- Sorts WN internally when needed for interpolation.\n\n' ...
                'What this correction does NOT do:\n' ...
                '- It does not fix low-quality spectra.\n' ...
                '- It does not remove noise.\n' ...
                '- It does not correct biological or acquisition artifacts.\n\n' ...
                'Recommended use:\n' ...
                '- Use this only when spectra have almost the same WN range but slightly different axes.\n' ...
                '- Do not use this if the WN ranges are completely different or if files are from incompatible acquisition settings.\n\n' ...
                '- Linear shift spectra: %d\n' ...
                '- Non-linear shift spectra: %d\n' ...
                '- Point-number mismatch spectra: %d\n\n' ...
                'Meaning:\n' ...
                '- Linear shift: WN axis is shifted by an almost constant offset.\n' ...
                '- Non-linear shift: WN difference changes across the spectrum.\n' ...
                '- Point-number mismatch: spectra cannot be stacked into one matrix without interpolation.\n\n' ...
                'Solution:\n' ...
                '1. Interpolate to common grid for rigorous preprocessing/analysis.\n' ...
                '2. Accept as-is only if WN differences are negligible and same-length.\n' ...
                '3. Reload/reorganize data if WN ranges or acquisition settings are incompatible.\n\n' ...
                'Continue WN interpolation?'], ...
                mismatchCount, duplicateCount, nonMonoCount, ...
                linearCount, nonlinearCount, lengthCount);
            
            choice = questdlg( ...
                msg, ...
                'WN correction warning', ...
                'Continue interpolation', ...
                'Cancel', ...
                'Cancel');
            
            ok = strcmp(choice, 'Continue interpolation');
        end
        
        
        function showRawSNRCorrectionWarning(app)
            
            qc = app.singleQC;
            
            lowCount = NaN;
            threshold = NaN;
            medianSNR = NaN;
            
            if isfield(qc, 'LowSNRSpectra')
                lowCount = qc.LowSNRSpectra;
            end
            
            if isfield(qc, 'LowSNRThreshold')
                threshold = qc.LowSNRThreshold;
            end
            
            if isfield(qc, 'MedianSNR')
                medianSNR = qc.MedianSNR;
            end
            
            msg = sprintf([ ...
                'RAW SNR WARNING\n\n' ...
                'Detected low/invalid SNR spectra: %d\n' ...
                'Current SNR threshold: %.2f\n' ...
                'Median raw SNR: %.2f\n\n' ...
                'Important:\n' ...
                'Raw SNR is a data-quality warning, not a technical axis mismatch.\n\n' ...
                'The app should NOT automatically correct low SNR because smoothing or filtering only to increase SNR may distort real Raman features.\n\n' ...
                'Recommended actions:\n' ...
                '1. Use Detailed warning to inspect low-SNR spectra visually.\n' ...
                '2. Decide whether low SNR is caused by bad acquisition, weak sample signal, fluorescence background, or true biology.\n' ...
                '3. Keep, flag, or exclude spectra later using a documented rule.\n' ...
                '4. Do not hide low SNR by automatic correction.\n\n' ...
                'No automatic SNR correction will be performed.'], ...
                lowCount, threshold, medianSNR);
            
            uialert(app.UIFigure, msg, 'Raw SNR warning');
        end
        
        
        function refreshSingleQCStateAfterCorrection( ...
                app, correctionName)
            
            if nargin < 2
                correctionName = ...
                    "QC correction";
            end
            
            
            % =====================================================
            % RE-RUN QC
            % =====================================================
            
            app.singleQC = ...
                app.runSingleInitialQC();
            
            
            % =====================================================
            % RE-LINK DATA
            % =====================================================
            
            linkedOK = ...
                app.promoteSingleHomeDataToWorkspace();
            
            
            if linkedOK
                
                app.syncCurrentDataToAnalysis();
                
                app.refreshMainAxesAfterChild();
                
            end
            
            
            % =====================================================
            % SAVE QC STATE
            % =====================================================
            
            app.saveSingleQCReport(false);
            
            
            % =====================================================
            % HISTORY
            % =====================================================
            
            if app.processHistory == ""
                
                app.processHistory = ...
                    string(correctionName);
                
            else
                
                app.processHistory = ...
                    app.processHistory + ...
                    " --> " + ...
                    string(correctionName);
                
            end
            
            
            app.updateInfoField();
            
            
            % =====================================================
            % ALWAYS RETURN TO SUMMARY
            % =====================================================
            
            app.restoreTreeToGroupSelection();
            
            
            drawnow limitrate;
            
        end
        
        function color = getQCBackgroundColor(app, statusText)
            
            statusText = upper(string(statusText));
            
            switch statusText
                case "PASS"
                    color = [0.88 0.96 0.88];     % light green
                case "WARNING"
                    color = [1.00 0.96 0.80];     % light yellow
                case "FAIL"
                    color = [1.00 0.86 0.86];     % light red
                case "ACCEPTED"
                    color = [0.86 0.92 1.00];     % light blue
                otherwise
                    color = [0.94 0.94 0.94];     % light gray
            end
        end
        
        
        function showMetadataWarningInTree(app)
            
            if isempty(app.singleQC) || ...
                    ~isstruct(app.singleQC)
                
                msgbox( ...
                    'No metadata QC result is available.', ...
                    'Metadata details');
                
                return
            end
            
            
            app.singleWarningViewMode = "metadata";
            
            delete(app.Tree3.Children);
            
            
            % =====================================================
            % ROOT
            % =====================================================
            
            rootNode = uitreenode(app.Tree3);
            
            rootNode.Text = ...
                'Metadata QC details';
            
            rootNode.NodeData = struct( ...
                'Type', "metadata_root");
            
            nAdded = 0;
            
            
            % =====================================================
            % 1. GROUP FOLDER LABEL ISSUES
            % =====================================================
            
            if isfield(app.singleQC, 'MetadataDetails') && ...
                    ~isempty(app.singleQC.MetadataDetails)
                
                T = app.singleQC.MetadataDetails;
                
                if ismember('InvalidLabel', ...
                        T.Properties.VariableNames)
                    
                    badRows = find(T.InvalidLabel);
                    
                    if ~isempty(badRows)
                        
                        labelRoot = uitreenode(rootNode);
                        
                        labelRoot.Text = sprintf( ...
                            'Group folder label issues (%d)', ...
                            numel(badRows));
                        
                        labelRoot.NodeData = struct( ...
                            'Type', ...
                            "metadata_group_label_root");
                        
                        
                        for i = 1:numel(badRows)
                            
                            r = badRows(i);
                            
                            folderName = ...
                                string(T.FolderDisplayName(r));
                            
                            groupID = ...
                                string(T.GroupID(r));
                            
                            folderPath = ...
                                string(T.FolderPath(r));
                            
                            reasonText = ...
                                string(T.Reason(r));
                            
                            
                            node = uitreenode(labelRoot);
                            
                            node.Text = sprintf( ...
                                '%s | %s', ...
                                folderName, ...
                                reasonText);
                            
                            node.NodeData = struct( ...
                                'Type', ...
                                "metadata_group_label_issue", ...
                                'FolderName', ...
                                folderName, ...
                                'GroupID', ...
                                groupID, ...
                                'FolderPath', ...
                                folderPath, ...
                                'IssueText', ...
                                reasonText);
                            
                            nAdded = nAdded + 1;
                            
                        end
                        
                        expand(labelRoot);
                        
                    end
                end
            end
            
            
            % =====================================================
            % 2. FOLDER ORGANIZATION / LOAD ISSUES
            % =====================================================
            
            loadIssues = strings(0,1);
            
            if isfield(app.singleQC, ...
                    'MetadataLoadIssues')
                
                loadIssues = string( ...
                    app.singleQC.MetadataLoadIssues(:));
                
            end
            
            loadIssues = strtrim(loadIssues);
            
            loadIssues = ...
                loadIssues(loadIssues ~= "");
            
            
            if ~isempty(loadIssues)
                
                loadRoot = uitreenode(rootNode);
                
                loadRoot.Text = sprintf( ...
                    'Folder / loading issues (%d)', ...
                    numel(loadIssues));
                
                loadRoot.NodeData = struct( ...
                    'Type', ...
                    "metadata_load_root");
                
                
                for i = 1:numel(loadIssues)
                    
                    node = uitreenode(loadRoot);
                    
                    node.Text = char(loadIssues(i));
                    
                    node.NodeData = struct( ...
                        'Type', ...
                        "metadata_load_issue", ...
                        'IssueIndex', ...
                        i, ...
                        'IssueText', ...
                        loadIssues(i));
                    
                    nAdded = nAdded + 1;
                    
                end
                
                expand(loadRoot);
                
            end
            
            
            % =====================================================
            % 3. TABLE CONSISTENCY
            % =====================================================
            
            if isfield(app.singleQC, ...
                    'MetadataFileTableMismatch') && ...
                    app.singleQC.MetadataFileTableMismatch
                
                node = uitreenode(rootNode);
                
                node.Text = ...
                    'Metadata table consistency issue';
                
                node.NodeData = struct( ...
                    'Type', ...
                    "metadata_table_issue", ...
                    'IssueText', ...
                    "File table size does not match loaded spectra count.");
                
                nAdded = nAdded + 1;
                
            end
            
            
            % =====================================================
            % 4. DATA STRUCTURE REASONS
            % =====================================================
            
            if isfield(app.singleQC, ...
                    'MetadataDataStructureReasons')
                
                reasons = string( ...
                    app.singleQC.MetadataDataStructureReasons(:));
                
                reasons = ...
                    reasons(strtrim(reasons) ~= "");
                
                if ~isempty(reasons)
                    
                    structureRoot = ...
                        uitreenode(rootNode);
                    
                    structureRoot.Text = ...
                        'Data structure issues';
                    
                    structureRoot.NodeData = struct( ...
                        'Type', ...
                        "metadata_structure_root");
                    
                    
                    for i = 1:numel(reasons)
                        
                        node = ...
                            uitreenode(structureRoot);
                        
                        node.Text = ...
                            char(reasons(i));
                        
                        node.NodeData = struct( ...
                            'Type', ...
                            "metadata_structure_issue", ...
                            'IssueText', ...
                            reasons(i));
                        
                        nAdded = nAdded + 1;
                        
                    end
                    
                    expand(structureRoot);
                    
                end
            end
            
            
            % =====================================================
            % NO ISSUE
            % =====================================================
            
            if nAdded == 0
                
                node = uitreenode(rootNode);
                
                node.Text = ...
                    'No metadata issue detected';
                
                node.NodeData = struct( ...
                    'Type', ...
                    "metadata_no_issue");
                
            end
            
            
            expand(rootNode);
            
        end
        
        function issueText = getMetadataIssueForSpectrum(app, k)
            
            issueText = "";
            
            if ~isfield(app.singleData, 'Names') || k > numel(app.singleData.Names) || strlength(string(app.singleData.Names(k))) == 0
                issueText = "missing spectrum name";
                return
            end
            
            if ~isfield(app.singleData, 'FilePaths') || k > numel(app.singleData.FilePaths) || strlength(string(app.singleData.FilePaths(k))) == 0
                issueText = "missing file path";
                return
            end
            
            if ~isfile(app.singleData.FilePaths(k))
                issueText = "file path not found";
                return
            end
            
            if ~isfield(app.singleData, 'GroupIDs') || k > numel(app.singleData.GroupIDs) || strlength(string(app.singleData.GroupIDs(k))) == 0
                issueText = "missing group label";
                return
            end
            
            groupID = string(app.singleData.GroupIDs(k));
            
            if ~app.isStrictGroupLabel(groupID)
                issueText = "invalid group label: " + app.getStrictLabelIssue(groupID);
                return
            end
            
            if ~isfield(app.singleData, 'WnCell') || ~isfield(app.singleData, 'SpectraCell')
                issueText = "missing WN/spectrum cell";
                return
            end
            
            if k > numel(app.singleData.WnCell) || k > numel(app.singleData.SpectraCell)
                issueText = "metadata length mismatch";
                return
            end
            
            wnK = app.singleData.WnCell{k};
            yK = app.singleData.SpectraCell{k};
            
            if isempty(wnK) || isempty(yK)
                issueText = "empty spectrum";
                return
            end
            
            if numel(wnK) ~= numel(yK)
                issueText = "WN/intensity point mismatch";
                return
            end
        end
        
        
        function showSNRWarningInTree(app)
            
            if isempty(app.singleQC) || ~isstruct(app.singleQC) || ~isfield(app.singleQC, 'SNRValues')
                uialert(app.UIFigure, ...
                    'No SNR values found. Please run Access data first.', ...
                    'SNR warning');
                return
            end
            
            app.singleWarningViewMode = "snr";
            
            delete(app.Tree3.Children);
            
            rootNode = uitreenode(app.Tree3);
            rootNode.Text = sprintf('SNR weakness | threshold %.2f', app.singleQC.LowSNRThreshold);
            rootNode.NodeData = struct('Type', "snr_root");
            
            snrVals = app.singleQC.SNRValues(:);
            threshold = app.singleQC.LowSNRThreshold;
            
            badIdx = find(~isfinite(snrVals) | snrVals < threshold);
            
            if isempty(badIdx)
                node = uitreenode(rootNode);
                node.Text = 'No low-SNR spectrum under current threshold.';
                node.NodeData = struct('Type', "snr_info");
            else
                for ii = 1:numel(badIdx)
                    k = badIdx(ii);
                    
                    if isfinite(snrVals(k))
                        snrText = sprintf('SNR %.2f', snrVals(k));
                    else
                        snrText = 'SNR invalid';
                    end
                    
                    node = uitreenode(rootNode);
                    node.Text = sprintf('%03d | %s | %s', ...
                        k, ...
                        app.getSingleSpectrumName(k), ...
                        snrText);
                    
                    node.NodeData = struct( ...
                        'Type', "snr_warning", ...
                        'SpectrumIndex', k, ...
                        'SNR', snrVals(k));
                end
            end
            
            expand(rootNode);
            
            app.TextArea_5.Value = { ...
                'SNR detailed warning mode'; ...
                ''; ...
                'Select a low-SNR spectrum in Tree3 to display it on UIAxes8.'; ...
                ''; ...
                'Meaning:'; ...
                '- low SNR may reflect weak Raman signal'; ...
                '- or fluorescence/background/noisy acquisition'; ...
                '- inspect the spectral shape before accepting'};
        end
        
        
        function name = getSingleSpectrumName(app, k)
            
            name = "Spectrum_" + string(k);
            
            if isfield(app.singleData, 'Names') && numel(app.singleData.Names) >= k
                if strlength(string(app.singleData.Names(k))) > 0
                    name = string(app.singleData.Names(k));
                end
            end
        end
        
        
        function plotSingleWarningSpectrum(app, idx)
            
            if isempty(app.singleData) || ...
                    ~isfield(app.singleData, 'WnCell') || ...
                    ~isfield(app.singleData, 'SpectraCell') || ...
                    idx < 1 || ...
                    idx > numel(app.singleData.SpectraCell)
                
                uialert(app.UIFigure, 'Selected spectrum is not available.', 'Plot warning');
                return
            end
            
            wnK = app.singleData.WnCell{idx};
            yK = app.singleData.SpectraCell{idx};
            
            if isempty(wnK) || isempty(yK) || numel(wnK) ~= numel(yK)
                cla(app.UIAxes8, 'reset');
                title(app.UIAxes8, sprintf('Invalid spectrum %d', idx));
                return
            end
            
            cla(app.UIAxes8, 'reset');
            
            plot(app.UIAxes8, wnK, yK, 'LineWidth', 1.5);
            
            app.UIAxes8.Box = 'on';
            grid(app.UIAxes8, 'off');
            
            xlabel(app.UIAxes8, 'Raman shift (cm^{-1})');
            ylabel(app.UIAxes8, 'Intensity (a.u.)');
            
            title(app.UIAxes8, sprintf('Selected warning spectrum %d | %s', ...
                idx, app.getSingleSpectrumName(idx)), ...
                'Interpreter', 'none');
            
            xlim(app.UIAxes8, [min(wnK) max(wnK)]);
            
            yMin = min(yK, [], 'omitnan');
            yMax = max(yK, [], 'omitnan');
            yRange = yMax - yMin;
            
            if isfinite(yRange) && yRange > 0
                ylim(app.UIAxes8, [yMin - 0.08*yRange, yMax + 0.08*yRange]);
            end
            
            drawnow limitrate;
        end
        
        
        function adjustOrAcceptSNRThreshold(app)
            
            if isempty(app.singleQC) || ~isstruct(app.singleQC) || ~isfield(app.singleQC, 'LowSNRThreshold')
                uialert(app.UIFigure, ...
                    'No SNR QC result found. Please run Access data first.', ...
                    'SNR correction');
                return
            end
            
            qc = app.singleQC;
            
            msg = sprintf([ ...
                'SNR THRESHOLD / ACCEPTANCE\n\n' ...
                'Current threshold: %.2f\n' ...
                'Median SNR: %.2f\n' ...
                'Low/invalid SNR spectra: %d\n\n' ...
                'Choose one action:\n\n' ...
                '1. Lower threshold:\n' ...
                'Use this if the current threshold is too strict for this dataset.\n\n' ...
                '2. Accept current SNR warning:\n' ...
                'Use this only after visual inspection. This does not change spectra.\n\n' ...
                'Scientific note:\n' ...
                'This app should not smooth/correct spectra only to force SNR PASS.'], ...
                qc.LowSNRThreshold, qc.MedianSNR, qc.LowSNRSpectra);
            
            choice = questdlg( ...
                msg, ...
                'SNR threshold / acceptance', ...
                'Lower threshold', ...
                'Accept current SNR', ...
                'Cancel', ...
                'Cancel');
            
            if isempty(choice) || strcmp(choice, 'Cancel')
                return
            end
            
            switch choice
                
                case 'Lower threshold'
                    
                    answ = inputdlg( ...
                        {'Enter new raw SNR threshold:'}, ...
                        'Lower SNR threshold', ...
                        [1 45], ...
                        {num2str(app.singleSNRThreshold)});
                    
                    if isempty(answ)
                        return
                    end
                    
                    newThr = str2double(answ{1});
                    
                    if isnan(newThr) || newThr <= 0
                        uialert(app.UIFigure, ...
                            'SNR threshold must be a positive number.', ...
                            'Invalid threshold');
                        return
                    end
                    
                    app.singleSNRThreshold = newThr;
                    app.singleSNRAccepted = false;
                    app.singleSNRAcceptNote = "SNR threshold changed to " + string(newThr) + " on " + string(datetime('now'));
                    
                case 'Accept current SNR'
                    
                    confirm = questdlg( ...
                        sprintf([ ...
                        'Accept current low-SNR spectra for analysis?\n\n' ...
                        'This will not modify the spectra.\n' ...
                        'It only records that the user accepted the current SNR warning.\n\n' ...
                        'Recommended only after visual inspection using Detailed warning.']), ...
                        'Accept SNR warning', ...
                        'Accept SNR', ...
                        'Cancel', ...
                        'Cancel');
                    
                    if ~strcmp(confirm, 'Accept SNR')
                        return
                    end
                    
                    app.singleSNRAccepted = true;
                    app.singleSNRAcceptNote = "Low-SNR spectra accepted by user on " + string(datetime('now'));
            end
            
            app.refreshSingleQCStateAfterCorrection( ...
                "SNR QC setting updated");
            
            msgbox('SNR QC setting was updated. QC summary has been refreshed.', ...
                'SNR QC updated');
        end
        
        
        function restoreTreeToGroupSelection(app)
            
            % Exit Details mode.
            app.singleWarningViewMode = "";
            
            % Rebuild normal group tree.
            app.populateSingleHomeTree();
            
            % Restore previously accessed group selection.
            app.restoreLastSelectedGroupsInTree();
            
            
            % Restore spectral overview.
            if ~isempty(app.singleData) && ...
                    isstruct(app.singleData) && ...
                    isfield(app.singleData, 'SpectraCell') && ...
                    ~isempty(app.singleData.SpectraCell)
                
                app.plotSingleHomeSpectra();
                
            else
                
                cla(app.UIAxes8, 'reset');
                
                title(app.UIAxes8, ...
                    'Spectral review');
                
                xlabel(app.UIAxes8, ...
                    'Raman shift (cm^{-1})');
                
                ylabel(app.UIAxes8, ...
                    'Intensity (a.u.)');
                
                app.UIAxes8.Box = 'on';
                
                grid(app.UIAxes8, 'off');
                
            end
            
            
            % Restore QC summary cards.
            if ~isempty(app.singleQC) && ...
                    isstruct(app.singleQC) && ...
                    isfield(app.singleQC, 'Status')
                
                app.updateSingleQCDisplay();
                
            end
            
            drawnow limitrate;
            
        end
        
        function data = readRamanTxtFlexible(app, filePath)
            
            raw = fileread(filePath);
            
            raw = replace(raw, sprintf('\r\n'), sprintf('\n'));
            raw = replace(raw, sprintf('\r'), sprintf('\n'));
            
            lines = splitlines(string(raw));
            lines = strtrim(lines);
            lines = lines(lines ~= "");
            
            data = [];
            
            for i = 1:numel(lines)
                
                line = strtrim(lines(i));
                
                if line == "" || startsWith(line, ["#", "%", "//"])
                    continue
                end
                
                % Case 1:
                % Có tab hoặc nhiều space giữa 2 cột.
                % Ví dụ:
                % 320,76   4785
                % 320.76   4785
                parts = regexp(char(line), '\s+', 'split');
                parts = parts(~cellfun(@isempty, parts));
                
                if numel(parts) >= 2
                    token1 = string(parts{1});
                    token2 = string(parts{2});
                    
                else
                    % Case 2:
                    % Có dấu ; làm separator
                    if contains(line, ";")
                        parts = split(line, ";");
                        parts = strtrim(parts);
                        
                        if numel(parts) < 2
                            continue
                        end
                        
                        token1 = parts(1);
                        token2 = parts(2);
                        
                        % Case 3:
                        % Có comma làm separator kiểu 320.76,4785
                        % Chỉ xử lý khi có đúng 1 dấu phẩy và không có khoảng trắng.
                    elseif count(line, ",") == 1 && ~contains(line, ".")
                        % Đây nhiều khả năng là decimal comma một số đơn, không phải 2 cột
                        continue
                        
                    elseif count(line, ",") == 1 && contains(line, ".")
                        parts = split(line, ",");
                        parts = strtrim(parts);
                        
                        if numel(parts) < 2
                            continue
                        end
                        
                        token1 = parts(1);
                        token2 = parts(2);
                        
                    else
                        continue
                    end
                end
                
                % Đổi decimal comma thành decimal dot trong từng token riêng biệt
                token1 = replace(token1, ",", ".");
                token2 = replace(token2, ",", ".");
                
                wn = str2double(token1);
                intensity = str2double(token2);
                
                if isfinite(wn) && isfinite(intensity)
                    data(end+1, :) = [wn intensity]; %#ok<AGROW>
                end
            end
            
            if isempty(data)
                error('Cannot read numeric Raman data from file: %s', filePath);
            end
        end


        function logActivity(app, stageName, actionName, methodName, statusText, detailText)
            
            if nargin < 6
                detailText = "";
            end
            
            newRow = table( ...
                string(datetime('now','Format','yyyy-MM-dd HH:mm:ss')), ...
                string(stageName), ...
                string(actionName), ...
                string(methodName), ...
                string(statusText), ...
                string(detailText), ...
                'VariableNames', {'Time','Stage','Action','Method','Status','Detail'});
            
            if isempty(app.ActivityHistory)
                app.ActivityHistory = newRow;
            else
                app.ActivityHistory = [app.ActivityHistory; newRow];
            end
            
            app.updateActivityHistoryDisplay();
            
            if ~isempty(app.mainHomeApp) && isvalid(app.mainHomeApp) && ...
                    any(strcmp(methods(app.mainHomeApp), 'logSessionEvent'))
                
                app.mainHomeApp.logSessionEvent( ...
                    class(app), ...
                    char(actionName), ...
                    char(detailText));
            end
        end
        
        
        function updateActivityHistoryDisplay(app)
            
            if ~isprop(app, 'TextArea_6') || ~isvalid(app.TextArea_6)
                return
            end
            
            if isempty(app.ActivityHistory)
                app.TextArea_6.Value = {'Activity history:'};
                return
            end
            
            lines = strings(0,1);
            lines(end+1,1) = "Activity history:";
            lines(end+1,1) = "";
            
            n = height(app.ActivityHistory);
            idx = max(1, n-20):n;
            
            for i = idx
                lines(end+1,1) = sprintf('%s | %s | %s | %s | %s', ...
                    app.ActivityHistory.Time(i), ...
                    app.ActivityHistory.Stage(i), ...
                    app.ActivityHistory.Action(i), ...
                    app.ActivityHistory.Method(i), ...
                    app.ActivityHistory.Status(i));
            end
            
            app.TextArea_6.Value = cellstr(lines);
        end
        
        
        function summaryText = buildSingleModeSummary(app)
            
            lines = strings(0,1);
            
            lines(end+1,1) = "SINGLE MODE SESSION SUMMARY";
            lines(end+1,1) = "User: " + app.homeUser;
            lines(end+1,1) = "Input folder: " + app.singleInputFolder;
            lines(end+1,1) = "Output folder: " + app.singleOutputSessionFolder;
            lines(end+1,1) = "";
            
            if ~isempty(app.singleQC) && isstruct(app.singleQC) && isfield(app.singleQC, 'Status')
                lines(end+1,1) = "QC status: " + string(app.singleQC.Status);
                lines(end+1,1) = "Groups: " + string(app.singleQC.TotalGroups);
                lines(end+1,1) = "Spectra: " + string(app.singleQC.TotalSpectra);
                lines(end+1,1) = "Median SNR: " + string(app.singleQC.MedianSNR);
            else
                lines(end+1,1) = "QC status: N/A";
            end
            
            lines(end+1,1) = "";
            lines(end+1,1) = "Process history: " + app.processHistory;
            lines(end+1,1) = "Confirmed preprocess stages: " + string(height(app.ConfirmedStages));
            lines(end+1,1) = "Recorded activities: " + string(height(app.ActivityHistory));
            
            summaryText = strjoin(lines, newline);
        end
        
        
        function saveActivityHistory(app, showMessage)
            
            if nargin < 2
                showMessage = true;
            end
            
            if isempty(app.singleOutputSessionFolder)
                app.createSingleOutputSessionFolder();
            end
            
            auditFolder = fullfile(app.singleOutputSessionFolder, '05_Report_Audit');
            
            if ~isfolder(auditFolder)
                mkdir(auditFolder);
            end
            
            if ~isempty(app.ActivityHistory)
                writetable(app.ActivityHistory, ...
                    fullfile(auditFolder, 'activity_history.tsv'), ...
                    'FileType', 'text', ...
                    'Delimiter', '\t');
            end
            
            if ~isempty(app.ConfirmedStages)
                writetable(app.ConfirmedStages, ...
                    fullfile(auditFolder, 'confirmed_stages.tsv'), ...
                    'FileType', 'text', ...
                    'Delimiter', '\t');
            end
            
            fid = fopen(fullfile(auditFolder, 'session_summary.txt'), 'w');
            
            if fid ~= -1
                fprintf(fid, '%s\n', app.buildSingleModeSummary());
                fclose(fid);
            end
            
            if showMessage
                msgbox(sprintf('Activity history saved to:\n%s', auditFolder), ...
                    'Activity history');
            end
        end
        
        function tf = isStrictGroupLabel(app, labelText)
            
            labelText = ...
                strtrim( ...
                string(labelText));
            
            tf = false;
            
            if labelText == ""
                return
            end
            
            % =============================================================
            % GROUP LABEL RULE
            %
            % Valid examples:
            %   BJ_NIV1_SAMPLE_1
            %   PATGV_NIV2_SAMPLE_4
            %   Control_1
            %   A_B_C_D
            %
            % Requirements:
            %   - ASCII letters and digits only
            %   - blocks separated by exactly one underscore
            %   - at least two blocks
            %   - numeric blocks are allowed
            % =============================================================
            
            pattern = ...
                '^[A-Za-z0-9]+(?:_[A-Za-z0-9]+)+$';
            
            tf = ...
                ~isempty( ...
                regexp( ...
                char(labelText), ...
                pattern, ...
                'once'));
            
        end
        
        function reason = getStrictLabelIssue(app, labelText)
            
            labelText = ...
                strtrim( ...
                string(labelText));
            
            if labelText == ""
                
                reason = ...
                    "empty group label";
                
                return
            end
            
            if contains(labelText, " ")
                
                reason = ...
                    "group label contains whitespace";
                
                return
            end
            
            if ~isempty( ...
                    regexp( ...
                    char(labelText), ...
                    '[^\x00-\x7F]', ...
                    'once'))
                
                reason = ...
                    "group label contains non-ASCII/accent character";
                
                return
            end
            
            if startsWith(labelText, "_") || ...
                    endsWith(labelText, "_")
                
                reason = ...
                    "group label cannot start or end with underscore";
                
                return
            end
            
            if contains(labelText, "__")
                
                reason = ...
                    "group label contains consecutive underscores";
                
                return
            end
            
            if ~contains(labelText, "_")
                
                reason = ...
                    "group label must contain at least two underscore-separated blocks";
                
                return
            end
            
            if ~isempty( ...
                    regexp( ...
                    char(labelText), ...
                    '[^A-Za-z0-9_]', ...
                    'once'))
                
                reason = ...
                    "group label contains characters other than letters, digits, or underscore";
                
                return
            end
            
            if ~app.isStrictGroupLabel(labelText)
                
                reason = ...
                    "group label has an invalid underscore-separated block structure";
                
                return
            end
            
            reason = ...
                "valid group label";
            
        end
        
        
        function showWNWarningInTree(app)
            
            if isempty(app.singleQC) || ~isstruct(app.singleQC) || ~isfield(app.singleQC, 'WnDetails')
                uialert(app.UIFigure, ...
                    'No WN detail table found. Please run Access data first.', ...
                    'WN warning');
                return
            end
            
            app.singleWarningViewMode = "wn";
            
            delete(app.Tree3.Children);
            
            rootNode = uitreenode(app.Tree3);
            rootNode.Text = 'Wavenumber axis warning';
            rootNode.NodeData = struct('Type', "wn_root");
            
            T = app.singleQC.WnDetails;
            
            bad = T.Mismatch | T.DuplicatedWN | T.NonMonotonicWN | ...
                T.LengthMismatch | T.RangeMismatch | ...
                T.LinearShift | T.NonLinearShift;
            
            badIdx = find(bad);
            
            if isempty(badIdx)
                node = uitreenode(rootNode);
                node.Text = 'No WN warning detail found.';
                node.NodeData = struct('Type', "wn_info");
            else
                for ii = 1:numel(badIdx)
                    k = badIdx(ii);
                    
                    node = uitreenode(rootNode);
                    node.Text = sprintf('%03d | %s | %s', ...
                        k, ...
                        string(T.SpectrumName(k)), ...
                        string(T.IssueType(k)));
                    
                    node.NodeData = struct( ...
                        'Type', "wn_warning", ...
                        'SpectrumIndex', k, ...
                        'IssueType', string(T.IssueType(k)), ...
                        'MaxAbsDelta', T.MaxAbsDelta_cm1(k), ...
                        'MeanDelta', T.MeanDelta_cm1(k), ...
                        'ResidualDelta', T.ResidualDelta_cm1(k));
                end
            end
            
            expand(rootNode);
            
            app.TextArea_4.Value = { ...
                'WN detailed warning mode'; ...
                ''; ...
                'Tree3 now shows spectrum-level WN axis issues.'; ...
                ''; ...
                'Issue types:'; ...
                '- linear_shift: nearly constant WN offset'; ...
                '- nonlinear_shift: WN-dependent axis distortion'; ...
                '- point_number_mismatch: different WN length'; ...
                '- duplicated_wn: repeated WN values'; ...
                '- non_monotonic_wn: WN axis not ordered'; ...
                ''; ...
                'Recommended solution:'; ...
                '- Linear small shift: accept as-is or interpolate.'; ...
                '- Non-linear shift: interpolate if same range, otherwise reload/reorganize.'; ...
                '- Length mismatch: interpolate before matrix analysis.'};
        end
        
        function acceptWNAxisAsIs(app)
            
            if isempty(app.singleQC) || ~isstruct(app.singleQC)
                uialert(app.UIFigure, ...
                    'No QC result found. Please run Access data first.', ...
                    'No QC data');
                return
            end
            
            qc = app.singleQC;
            
            if isfield(qc, 'WnLengthMismatchCount') && qc.WnLengthMismatchCount > 0
                choice = questdlg( ...
                    sprintf(['Some spectra have different WN point numbers.\n\n' ...
                    'Point-number mismatch: %d\n\n' ...
                    'Hard accept cannot build a consistent matrix when lengths differ.\n' ...
                    'Interpolation is recommended.\n\n' ...
                    'Do you still want to record WN warning as accepted?'], ...
                    qc.WnLengthMismatchCount), ...
                    'Accept WN warning', ...
                    'Record accept only', ...
                    'Cancel', ...
                    'Cancel');
                
                if ~strcmp(choice, 'Record accept only')
                    return
                end
            else
                choice = questdlg( ...
                    sprintf(['Accept WN axis warning as-is?\n\n' ...
                    'This means the app will keep the selected spectra without interpolation.\n\n' ...
                    'Use this only when WN differences are scientifically negligible, ' ...
                    'for example very small linear shifts within instrument tolerance.\n\n' ...
                    'For non-linear shifts, interpolation or data reorganization is safer.\n\n' ...
                    'Continue?']), ...
                    'Accept WN warning', ...
                    'Accept as-is', ...
                    'Cancel', ...
                    'Cancel');
                
                if ~strcmp(choice, 'Accept as-is')
                    return
                end
            end
            
            answer = inputdlg( ...
                {'Reason / note for accepting WN warning:'}, ...
                'WN accept note', ...
                [2 70], ...
                {'Accepted by user after visual/QC review.'});
            
            if isempty(answer)
                return
            end
            
            app.singleWNAccepted = true;
            app.singleWNAcceptNote = string(answer{1});
            app.singleWNAcceptedAt = string(datetime('now'));
            
            app.refreshSingleQCStateAfterCorrection( ...
                "WN warning accepted");
            
            app.logActivity("Review data", "Accept WN axis as-is", "WN QC", ...
                "ACCEPTED", app.singleWNAcceptNote);
            
            msgbox('WN warning was recorded as accepted. QC display has been updated.', ...
                'WN accepted');
        end
        
        
        function initializeStandaloneWorkspace(app)
            % ===== Resolve project root =====
            projectRoot = app.resolveProjectRoot();
            % ===== Default paths =====
            app.singleInputFolder = string( ...
                fullfile(projectRoot, 'Data input', 'single'));
            app.singleOutputRoot = string( ...
                fullfile(projectRoot, 'Data output', 'single'));
            % ===== Default standalone identity =====
            if app.homeUser == ""
                app.homeUser = "Standalone user";
            end
            if app.homeRole == ""
                app.homeRole = "standalone";
            end
            % ===== Single mode state =====
            app.mode = 1;
            % ===== UI =====
            app.prepareSingleHomeUI();
            app.populateSingleHomeTree();
            app.TabGroup.SelectedTab = app.HomeTab;
            app.UserTextArea.Editable = 'off';
            app.UserTextArea.Value = {char(app.homeUser)};
        end
        
        function root = resolveProjectRoot(app)
            here = fileparts(mfilename('fullpath'));
            candidates = {
                here
                fileparts(here)
                fileparts(fileparts(here))
                };
            root = '';
            for i = 1:numel(candidates)
                c = candidates{i};
                if ~isempty(c) && ...
                        isfolder(fullfile(c, 'Data input')) && ...
                        isfolder(fullfile(c, 'Data output'))
                    root = c;
                    return
                end
            end
            root = uigetdir(here, ...
                'Select RamanCube project folder');
            if isequal(root, 0)
                root = here;
            end
        end


        function mark = getQCMark(app, statusText)
            statusText = upper(string(statusText));
            switch statusText
                case "PASS"
                    mark = "[OK]";
                case "WARNING"
                    mark = "[!]";
                case "FAIL"
                    mark = "[X]";
                case "ACCEPTED"
                    mark = "[A]";
                otherwise
                    mark = "[-]";
            end
        end
        
        
        function restoreLastSelectedGroupsInTree(app)
            
            if isempty(app.singleLastSelectedGroups)
                return
            end
            
            lastGroups = string( ...
                app.singleLastSelectedGroups(:));
            
            selectedNodes = ...
                matlab.ui.container.TreeNode.empty;
            
            rootNodes = app.Tree3.Children;
            
            if isempty(rootNodes)
                return
            end
            
            for r = 1:numel(rootNodes)
                
                rootNode = rootNodes(r);
                
                childNodes = rootNode.Children;
                
                for k = 1:numel(childNodes)
                    
                    node = childNodes(k);
                    
                    nd = node.NodeData;
                    
                    if isempty(nd) || ~isstruct(nd)
                        continue
                    end
                    
                    if ~isfield(nd, 'Type') || ...
                            string(nd.Type) ~= "group"
                        continue
                    end
                    
                    if ~isfield(nd, 'GroupName')
                        continue
                    end
                    
                    groupName = string(nd.GroupName);
                    
                    if any(lastGroups == groupName)
                        
                        selectedNodes(end+1) = node; %#ok<AGROW>
                        
                    end
                    
                end
                
            end
            
            if ~isempty(selectedNodes)
                
                app.Tree3.SelectedNodes = selectedNodes;
                
            end
            
        end


        function ruleTable = readGroupLabelRules(app)
            
            ruleTable = table( ...
                strings(0,1), ...
                strings(0,1), ...
                'VariableNames', { ...
                'FolderName', ...
                'AppLabel'});
            
            
            ruleFile = fullfile( ...
                app.singleInputFolder, ...
                'group_label_rules.txt');
            
            
            if ~isfile(ruleFile)
                return
            end
            
            
            try
                
                T = readtable( ...
                    ruleFile, ...
                    'FileType', 'text', ...
                    'Delimiter', '\t', ...
                    'TextType', 'string');
                
                
                requiredVars = ...
                    ["FolderName", "AppLabel"];
                
                
                if ~all(ismember( ...
                        requiredVars, ...
                        string(T.Properties.VariableNames)))
                    
                    return
                    
                end
                
                
                T.FolderName = ...
                    strtrim(string(T.FolderName));
                
                T.AppLabel = ...
                    strtrim(string(T.AppLabel));
                
                
                valid = ...
                    T.FolderName ~= "" & ...
                    T.AppLabel ~= "";
                
                
                ruleTable = ...
                    T(valid, {'FolderName','AppLabel'});
                
                
            catch
                
                ruleTable = table( ...
                    strings(0,1), ...
                    strings(0,1), ...
                    'VariableNames', { ...
                    'FolderName', ...
                    'AppLabel'});
                
            end
            
        end
        
        
        function appLabel = ...
                resolveGroupLabelFromRules( ...
                app, ...
                folderName)
            
            folderName = ...
                strtrim(string(folderName));
            
            % Nếu tên folder đã đúng chuẩn thì dùng trực tiếp.
            % Không cho rule cũ ghi đè một tên hợp lệ.
            if app.isStrictGroupLabel(folderName)
                
                appLabel = ...
                    folderName;
                
                return
            end
            
            % Chỉ tìm rule khi tên folder vật lý chưa đúng chuẩn.
            appLabel = ...
                folderName;
            
            ruleTable = ...
                app.readGroupLabelRules();
            
            if isempty(ruleTable)
                return
            end
            
            folderColumn = ...
                strtrim( ...
                string(ruleTable.FolderName));
            
            matchIndex = ...
                find( ...
                strcmpi( ...
                folderColumn, ...
                folderName), ...
                1, ...
                'last');
            
            if isempty(matchIndex)
                return
            end
            
            candidateLabel = ...
                strtrim( ...
                string( ...
                ruleTable.AppLabel(matchIndex)));
            
            if app.isStrictGroupLabel(candidateLabel)
                
                appLabel = ...
                    candidateLabel;
            end
            
        end
        
        
        function writeGroupLabelRules( ...
                app, folderNames, appLabels)
            
            folderNames = ...
                string(folderNames(:));
            
            appLabels = ...
                string(appLabels(:));
            
            
            if numel(folderNames) ~= ...
                    numel(appLabels)
                
                error( ...
                    'Folder names and app labels must have equal lengths.');
                
            end
            
            
            oldRules = ...
                app.readGroupLabelRules();
            
            
            newRules = table( ...
                folderNames, ...
                appLabels, ...
                'VariableNames', { ...
                'FolderName', ...
                'AppLabel'});
            
            
            T = [oldRules; newRules];
            
            
            % Keep last rule for each physical folder.
            [~, ia] = unique( ...
                flipud(T.FolderName), ...
                'stable');
            
            keepIdx = ...
                height(T) - ia + 1;
            
            keepIdx = sort(keepIdx);
            
            T = T(keepIdx,:);
            
            
            ruleFile = fullfile( ...
                app.singleInputFolder, ...
                'group_label_rules.txt');
            
            
            writetable( ...
                T, ...
                ruleFile, ...
                'FileType', 'text', ...
                'Delimiter', '\t');
        end
        
        
        function reviewMeta = buildDataReviewMetadata(app)
            reviewMeta = table();
            if isempty(app.spectra)
                return
            end
            nSpectra = size(app.spectra, 2);
            % =========================================================
            % NAMES
            % =========================================================
            if ~isempty(app.names) && numel(app.names) == nSpectra
                spectrumNames = erase(string(app.names(:)), ".txt");
            else
                spectrumNames = "Spectrum_" + compose("%03d", (1:nSpectra)');
            end
            
            % =========================================================
            % GROUP IDS
            % =========================================================
            groupIDs = repmat("Ungrouped", nSpectra, 1);
            
            if ~isempty(app.singleData) && ...
                    isstruct(app.singleData) && ...
                    isfield(app.singleData, 'GroupIDs') && ...
                    numel(app.singleData.GroupIDs) == nSpectra
                
                groupIDs = string(app.singleData.GroupIDs(:));
            end
            
            % =========================================================
            % DISPLAY GROUP LABELS
            % =========================================================
            groupLabels = groupIDs;
            if ~isempty(app.singleData) && ...
                    isstruct(app.singleData) && ...
                    isfield(app.singleData, 'GroupLabels') && ...
                    numel(app.singleData.GroupLabels) == nSpectra
                
                groupLabels = string(app.singleData.GroupLabels(:));
            end
            % =========================================================
            % SOURCE FILE PATHS
            % =========================================================
            filePaths = strings(nSpectra, 1);
            if ~isempty(app.singleData) && ...
                    isstruct(app.singleData) && ...
                    isfield(app.singleData, 'FilePaths') && ...
                    numel(app.singleData.FilePaths) == nSpectra
                
                filePaths = string(app.singleData.FilePaths(:));
            end
            % =========================================================
            % SOURCE INDEX
            %
            % Index relative to the current parent dataset at the moment
            % the child app is opened.
            % =========================================================
            sourceIndex = (1:nSpectra)';
            
            reviewMeta = table( ...
                spectrumNames, ...
                groupIDs, ...
                groupLabels, ...
                filePaths, ...
                sourceIndex, ...
                'VariableNames', { ...
                'SpectrumName', ...
                'GroupID', ...
                'GroupLabel', ...
                'FilePath', ...
                'SourceIndex'});
        end


        function receiveReviewDefinedDataInternal( ...
                app, ...
                wn, ...
                reviewedSpectra, ...
                reviewedNames, ...
                reviewMeta, ...
                spectralDef, ...
                peakLibrary)
            
            % =========================================================
            % 1. BASIC VALIDATION
            % =========================================================
            if isempty(wn)
                error('receiveReviewDefinedData:EmptyWavenumber', ...
                    'The reviewed wavenumber axis is empty.');
            end
            
            if isempty(reviewedSpectra)
                error('receiveReviewDefinedData:EmptySpectra', ...
                    'No reviewed spectra were returned.');
            end
            
            wn = double(wn(:));
            reviewedNames = erase(string(reviewedNames(:)), ".txt");
            
            nSpectra = size(reviewedSpectra, 2);
            
            if size(reviewedSpectra, 1) ~= numel(wn)
                error('receiveReviewDefinedData:DimensionMismatch', ...
                    ['Reviewed spectra row count does not match ' ...
                    'the wavenumber axis.']);
            end
            
            if numel(reviewedNames) ~= nSpectra
                error('receiveReviewDefinedData:NameMismatch', ...
                    ['Reviewed spectrum names are not aligned ' ...
                    'with spectra columns.']);
            end
            
            if ~istable(reviewMeta) || height(reviewMeta) ~= nSpectra
                error('receiveReviewDefinedData:MetadataMismatch', ...
                    ['Reviewed metadata are not aligned ' ...
                    'with spectra columns.']);
            end
            
            requiredVars = [ ...
                "SpectrumName", ...
                "GroupID", ...
                "GroupLabel", ...
                "FilePath", ...
                "SourceIndex"];
            
            missingVars = setdiff( ...
                requiredVars, ...
                string(reviewMeta.Properties.VariableNames));
            
            if ~isempty(missingVars)
                error('receiveReviewDefinedData:MissingMetadata', ...
                    'Review metadata missing field(s): %s', ...
                    strjoin(missingVars, ', '));
            end
            
            % =========================================================
            % 2. NORMALIZE METADATA TYPES
            % =========================================================
            reviewMeta.SpectrumName = ...
                erase(string(reviewMeta.SpectrumName(:)), ".txt");
            
            reviewMeta.GroupID = ...
                string(reviewMeta.GroupID(:));
            
            reviewMeta.GroupLabel = ...
                string(reviewMeta.GroupLabel(:));
            
            reviewMeta.FilePath = ...
                string(reviewMeta.FilePath(:));
            
            reviewMeta.SourceIndex = ...
                reviewMeta.SourceIndex(:);
            
            % Keep names synchronized with the metadata table.
            reviewedNames = reviewMeta.SpectrumName;
            
            % =========================================================
            % 3. VALIDATE OPTIONAL STRUCTURES
            % =========================================================
            if isempty(spectralDef)
                spectralDef = struct();
            end
            
            if ~isstruct(spectralDef)
                error('receiveReviewDefinedData:InvalidDefinition', ...
                    'The spectral definition must be a structure.');
            end
            
            if isempty(peakLibrary)
                peakLibrary = table();
            end
            
            if ~istable(peakLibrary)
                error('receiveReviewDefinedData:InvalidPeakLibrary', ...
                    'The peak library must be a table.');
            end
            
            % =========================================================
            % 4. UPDATE CURRENT WORKING DATA
            % =========================================================
            app.mode = 1;
            app.wn = wn;
            app.spectra = reviewedSpectra;
            app.cube = [];
            
            app.names = reviewedNames;
            app.reviewMeta = reviewMeta;
            
            app.spectralDef = spectralDef;
            app.peakLibrary = peakLibrary;
            % =========================================================
            % REFRESH BAND CHECK LIBRARY
            % =========================================================
            app.refreshBandCheckList();
            
            % =========================================================
            % 5. PRESERVE ORIGINAL RAW DATA
            %
            % Only initialize rawSpectra if it does not already exist.
            % Reviewing or deleting spectra must not silently overwrite raw data.
            % =========================================================
            if isempty(app.rawSpectra)
                app.rawSpectra = reviewedSpectra;
            end
            
            if isempty(app.rawNames)
                app.rawNames = reviewedNames;
            end
            
            % =========================================================
            % 6. INVALIDATE ANALYSIS RESULTS
            %
            % Changes in selected spectra, metadata or spectral ranges make
            % previous ratio/PCA/classification results stale.
            % =========================================================
            app.clearDerivedAnalysisState();
            
            app.scanFeatures = struct( ...
                'Name', {}, ...
                'Type', {}, ...
                'Map', {}, ...
                'Formula', {}, ...
                'BandInfo', {}, ...
                'Source', {}, ...
                'CreatedAt', {});
            
            % =========================================================
            % 7. RESET DOWNSTREAM PREPROCESS CHECKPOINTS
            %
            % The reviewed data become the new input dataset. Previously stored
            % preprocessing outputs may no longer correspond to the same spectra.
            % =========================================================
            app.ensurePreprocessWorkflowState();
            
            stagesToReset = [ ...
                "Cosmic", ...
                "Baseline", ...
                "Smooth"];
            
            for iStage = 1:numel(stagesToReset)
                
                stageName = stagesToReset(iStage);
                stageField = char(stageName);
                
                emptyCP = app.createEmptyPreprocessCheckpoint();
                emptyCP.Stage = stageName;
                
                app.preprocessCheckpoints.(stageField) = emptyCP;
                
            end
            
            app.pendingPreprocessContext = struct();
            app.CurrentStage = "Define";
            app.CurrentDataStatus = "REVIEWED";
            
            % =========================================================
            % 8. UPDATE PROCESS HISTORY
            % =========================================================
            app.appendHistory("Spectra reviewed and defined");
            
            % =========================================================
            % 9. SYNCHRONIZE DATA USED BY ANALYSIS
            % =========================================================
            app.syncCurrentDataToAnalysis();
            
            % =========================================================
            % 10. CLEAR ANALYSIS AXES
            % =========================================================
            try
                cla(app.UIAxes4, 'reset');
                cla(app.UIAxes5, 'reset');
                cla(app.UIAxes4_2, 'reset');
                cla(app.UIAxes5_2, 'reset');
            catch
            end
            
            % =========================================================
            % 11. CLEAR ANALYSIS TREES
            % =========================================================
            try
                delete(app.Tree.Children);
                delete(app.Tree2.Children);
                delete(app.Tree_2.Children);
                delete(app.Tree2_2.Children);
            catch
            end
            
            % =========================================================
            % 12. REFRESH MAIN DISPLAY
            % =========================================================
            app.refreshMainAxesAfterChild();
            
            drawnow limitrate;
            
            % =========================================================
            % 13. AUDIT
            % =========================================================
            app.logActivity( ...
                "Define", ...
                "Review and define spectra", ...
                "Current data", ...
                "REVIEWED", ...
                "Spectra retained: " + string(nSpectra));
            
        end


        function returnToMainHome(app)
            
            % Prevent repeated close/return operations
            if app.isReturningToHome
                return
            end
            
            app.isReturningToHome = true;
            
            
            % =========================================================
            % Preserve parent reference
            % =========================================================
            homeApp = app.mainHomeApp;
            
            
            % =========================================================
            % Restore Home app
            % =========================================================
            if ~isempty(homeApp)
                
                try
                    
                    if isvalid(homeApp)
                        
                        homeApp.showHomeFromChild( ...
                            'Single mode');
                        
                    end
                    
                catch ME
                    warning( ...
                        'RamanCube:ReturnHomeFailed', ...
                        'Could not return to Home app: %s', ...
                        ME.message);
                end
                
            end
            
            
            % =========================================================
            % Delete only Single mode
            % =========================================================
            try
                
                if ~isempty(app.UIFigure) && ...
                        isvalid(app.UIFigure)
                    
                    app.UIFigure.CloseRequestFcn = '';
                    delete(app.UIFigure);
                    
                end
                
            catch ME
                warning( ...
                    'RamanCube:CloseSingleFailed', ...
                    'Could not close Single mode: %s', ...
                    ME.message);
            end
            
        end
        
        
        function UIFigureCloseRequest(app, event)
            
            choice = questdlg( ...
                sprintf([ ...
                'You are leaving Single mode.\n\n' ...
                'What do you want to do?']), ...
                'Close Single mode', ...
                'Save and return Home', ...
                'Return without saving', ...
                'Cancel', ...
                'Save and return Home');
            
            
            if isempty(choice) || strcmp(choice, 'Cancel')
                return
            end
            
            
            switch choice
                
                case 'Save and return Home'
                    
                    try
                        app.saveSingleHomeSession(false);
                        app.saveActivityHistory(false);
                    catch ME
                        
                        uialert(app.UIFigure, ...
                            sprintf( ...
                            'Session could not be saved.\n\n%s', ...
                            ME.message), ...
                            'Save error');
                        
                        return
                    end
                    
                    
                case 'Return without saving'
                    
                    % Intentionally no save
                    
            end
            
            
            app.returnToMainHome();
            
        end
        
        function runSVMClassification( ...
                app, ...
                X, ...
                groupLabels, ...
                groupNames, ...
                wn)
            
            % =============================================================
            % SVM CLASSIFICATION
            %
            % X received here is ALREADY normalized and cleaned.
            %
            % Never refer to Xwork inside this function.
            % =============================================================
            
            X = ...
                double(X);
            
            
            groupLabels = ...
                string(groupLabels(:));
            
            
            groupNames = ...
                string(groupNames(:));
            
            
            wn = ...
                double(wn(:));
            
            
            % =============================================================
            % 1. VALIDATE
            % =============================================================
            
            if isempty(X)
                
                error( ...
                    'SVM input matrix is empty.');
                
            end
            
            
            if size(X,1) ~= numel(groupLabels)
                
                error( ...
                    'SVM spectrum count does not match group labels.');
                
            end
            
            
            if size(X,2) ~= numel(wn)
                
                error( ...
                    'SVM predictor count does not match WN variables.');
                
            end
            
            
            presentGroups = ...
                unique( ...
                groupLabels, ...
                'stable');
            
            
            presentGroups = ...
                presentGroups( ...
                presentGroups ~= "");
            
            
            if numel(presentGroups) < 2
                
                error( ...
                    'SVM requires at least 2 classes.');
                
            end
            
            
            % =============================================================
            % 2. SVM KERNEL
            % =============================================================
            
            kernelOptions = { ...
                'Linear', ...
                'Gaussian'};
            
            
            [kernelSel, okKernel] = ...
                listdlg( ...
                'PromptString', ...
                'Select SVM kernel:', ...
                'SelectionMode', ...
                'single', ...
                'ListString', ...
                kernelOptions, ...
                'InitialValue', ...
                1, ...
                'ListSize', ...
                [260 120]);
            
            
            if ~okKernel || isempty(kernelSel)
                
                error( ...
                    'SVM was cancelled.');
                
            end
            
            
            kernelName = ...
                string(kernelOptions{kernelSel});
            
            
            switch kernelName
                
                case "Linear"
                    
                    kernelFunction = ...
                        'linear';
                    
                case "Gaussian"
                    
                    kernelFunction = ...
                        'gaussian';
                    
            end
            
            
            % =============================================================
            % 3. CROSS-VALIDATION FOLDS
            % =============================================================
            
            groupCounts = ...
                zeros(numel(presentGroups),1);
            
            
            for g = 1:numel(presentGroups)
                
                groupCounts(g) = ...
                    nnz(groupLabels == presentGroups(g));
                
            end
            
            
            maxFold = ...
                min(groupCounts);
            
            
            if maxFold < 2
                
                error( ...
                    ['Each SVM class needs at least ' ...
                    '2 spectra.']);
                
            end
            
            
            defaultFold = ...
                min(5,maxFold);
            
            
            answer = ...
                inputdlg( ...
                {'Cross-validation folds:'}, ...
                'SVM settings', ...
                [1 45], ...
                {num2str(defaultFold)});
            
            
            if isempty(answer)
                
                error( ...
                    'SVM was cancelled.');
                
            end
            
            
            kFold = ...
                round(str2double(answer{1}));
            
            
            if ~isfinite(kFold) || ...
                    kFold < 2 || ...
                    kFold > maxFold
                
                error( ...
                    'CV folds must be between 2 and %d.', ...
                    maxFold);
                
            end
            
            
            % =============================================================
            % 4. TRAIN ECOC SVM
            % =============================================================
            
            template = ...
                templateSVM( ...
                'KernelFunction', ...
                kernelFunction, ...
                'Standardize', ...
                false);
            
            
            mdl = ...
                fitcecoc( ...
                X, ...
                categorical(groupLabels), ...
                'Learners', ...
                template, ...
                'Coding', ...
                'onevsone');
            
            
            % Current-session model for spatial projection.
            app.classModel = ...
                mdl;
            
            
            app.classModelType = ...
                "SVM-" + kernelName;
            
            
            % =============================================================
            % 5. CROSS-VALIDATED PREDICTIONS
            % =============================================================
            
            cvMdl = ...
                crossval( ...
                mdl, ...
                'KFold', ...
                kFold);
            
            
            predicted = ...
                kfoldPredict(cvMdl);
            
            
            predicted = ...
                string(predicted);
            
            
            trueLabels = ...
                string(groupLabels);
            
            
            accuracy = ...
                100 * ...
                mean( ...
                predicted == trueLabels);
            
            
            % =============================================================
            % 6. CONFUSION MATRIX
            % =============================================================
            
            classOrder = ...
                groupNames(:);
            
            
            C = ...
                confusionmat( ...
                categorical(trueLabels), ...
                categorical(predicted), ...
                'Order', ...
                categorical(classOrder));
            
            
            rowNames = ...
                cellstr(classOrder);
            
            
            varNames = ...
                matlab.lang.makeValidName( ...
                cellstr( ...
                "Pred_" + classOrder));
            
            
            app.classConfusion = ...
                array2table( ...
                C, ...
                'VariableNames', ...
                varNames, ...
                'RowNames', ...
                rowNames);
            
            
            app.classPredictedLabels = ...
                predicted;
            
            
            app.classAccuracy = ...
                accuracy;
            
            
            % =============================================================
            % 7. VISUALIZATION
            %
            % IMPORTANT:
            % This PCA is only a visualization of SVM input.
            % It is NOT an SVM latent space.
            % =============================================================
            
            Xcentered = ...
                bsxfun( ...
                @minus, ...
                X, ...
                mean(X,1,'omitnan'));
            
            
            maxScoreComp = ...
                min([ ...
                5, ...
                size(Xcentered,1)-1, ...
                size(Xcentered,2)]);
            
            
            if maxScoreComp >= 1
                
                [ ...
                    coeff, ...
                    score, ...
                    ~, ...
                    ~, ...
                    explained] = ...
                    pca( ...
                    Xcentered, ...
                    'Centered', ...
                    false, ...
                    'Rows', ...
                    'complete', ...
                    'NumComponents', ...
                    maxScoreComp);
                
                
                app.classScores = ...
                    score;
                
                
                app.classExplained = ...
                    explained(:);
                
                
                app.classComponentNames = ...
                    "PC" + ...
                    string((1:maxScoreComp)');
                
                
            else
                
                coeff = [];
                
                app.classScores = [];
                
                app.classExplained = [];
                
                app.classComponentNames = ...
                    strings(0,1);
                
            end
            
            
            % =============================================================
            % 8. SPECTRAL IMPORTANCE
            % =============================================================
            
            if kernelName == "Linear"
                
                % ---------------------------------------------------------
                % Combine absolute linear coefficients from all binary
                % ECOC learners.
                % ---------------------------------------------------------
                
                importance = ...
                    zeros(size(X,2),1);
                
                
                learners = ...
                    mdl.BinaryLearners;
                
                
                for k = 1:numel(learners)
                    
                    L = ...
                        learners{k};
                    
                    
                    if isempty(L)
                        continue
                    end
                    
                    
                    if isprop(L,'Beta') && ...
                            ~isempty(L.Beta)
                        
                        beta = ...
                            double(L.Beta(:));
                        
                        
                        if numel(beta) == ...
                                size(X,2)
                            
                            importance = ...
                                importance + ...
                                abs(beta);
                            
                        end
                        
                    end
                    
                end
                
                
                if any(isfinite(importance)) && ...
                        max(importance) > 0
                    
                    importance = ...
                        importance ./ ...
                        max(importance);
                    
                end
                
                
                app.classLoadings = ...
                    importance;
                
                
            else
                
                % ---------------------------------------------------------
                % Gaussian SVM has no direct spectral coefficient.
                %
                % Use simple permutation importance.
                % ---------------------------------------------------------
                
                baselinePrediction = ...
                    predict( ...
                    mdl, ...
                    X);
                
                
                baselinePrediction = ...
                    string(baselinePrediction);
                
                
                baselineAccuracy = ...
                    mean( ...
                    baselinePrediction == ...
                    trueLabels);
                
                
                importance = ...
                    zeros(size(X,2),1);
                
                
                nObs = ...
                    size(X,1);
                
                
                for j = 1:size(X,2)
                    
                    Xperm = ...
                        X;
                    
                    
                    permOrder = ...
                        randperm(nObs);
                    
                    
                    Xperm(:,j) = ...
                        Xperm(permOrder,j);
                    
                    
                    predPerm = ...
                        predict( ...
                        mdl, ...
                        Xperm);
                    
                    
                    predPerm = ...
                        string(predPerm);
                    
                    
                    permAccuracy = ...
                        mean( ...
                        predPerm == trueLabels);
                    
                    
                    importance(j) = ...
                        max( ...
                        0, ...
                        baselineAccuracy - ...
                        permAccuracy);
                    
                end
                
                
                if max(importance) > 0
                    
                    importance = ...
                        importance ./ ...
                        max(importance);
                    
                end
                
                
                app.classLoadings = ...
                    importance;
                
            end
            
            
            % =============================================================
            % 9. CLASSIFICATION METADATA
            % =============================================================
            
            app.classMethod = ...
                "SVM-" + kernelName;
            
            
            app.classRunRecipe.Method = ...
                "SVM";
            
            
            app.classRunRecipe.Kernel = ...
                kernelName;
            
            
            app.classRunRecipe.CrossValidationFolds = ...
                kFold;
            
            
            app.classRunRecipe.AccuracyPercent = ...
                accuracy;
            
            
            app.classRunRecipe.GroupNames = ...
                groupNames(:);
            
            
            app.classRunRecipe.VariableCount = ...
                size(X,2);
            
            
            app.classRunRecipe.SpectrumCount = ...
                size(X,1);
            
        end
        
        
        function importance = computeSVMPermutationImportance( ...
                app, ...
                mdl, ...
                X, ...
                groupLabels)
            
            %#ok<INUSD>
            
            
            X = double(X);
            
            groupLabels = string(groupLabels(:));
            
            
            nVariables = size(X,2);
            
            importance = zeros(nVariables,1);
            
            
            % =========================================================
            % Baseline prediction accuracy
            % =========================================================
            baselinePred = string( ...
                predict(mdl, X));
            
            
            baselineAcc = mean( ...
                baselinePred == groupLabels);
            
            
            % =========================================================
            % Limit computation cost for large Raman spectra
            %
            % Evaluate variables in spectral blocks instead of every point
            % independently when variable number is large.
            % =========================================================
            maxBlocks = 250;
            
            
            if nVariables <= maxBlocks
                
                blockEdges = ...
                    [(1:nVariables)' (1:nVariables)'];
                
            else
                
                edges = round( ...
                    linspace( ...
                    1, ...
                    nVariables + 1, ...
                    maxBlocks + 1));
                
                
                blockEdges = zeros(maxBlocks,2);
                
                
                for b = 1:maxBlocks
                    
                    blockEdges(b,1) = ...
                        edges(b);
                    
                    blockEdges(b,2) = ...
                        max(edges(b), edges(b+1)-1);
                    
                end
                
            end
            
            
            nBlocks = size(blockEdges,1);
            
            
            % =========================================================
            % Permute each variable/block
            % =========================================================
            rngState = rng;
            
            cleanupRng = onCleanup( ...
                @() rng(rngState)); %#ok<NASGU>
            
            
            rng(1, 'twister');
            
            
            for b = 1:nBlocks
                
                idx1 = blockEdges(b,1);
                idx2 = blockEdges(b,2);
                
                idxBlock = idx1:idx2;
                
                
                Xperm = X;
                
                
                permOrder = ...
                    randperm(size(X,1));
                
                
                Xperm(:,idxBlock) = ...
                    Xperm(permOrder,idxBlock);
                
                
                permPred = string( ...
                    predict(mdl, Xperm));
                
                
                permAcc = mean( ...
                    permPred == groupLabels);
                
                
                dropAcc = ...
                    max(0, baselineAcc - permAcc);
                
                
                importance(idxBlock) = ...
                    dropAcc;
                
            end
            
        end


        function exportSelectedReportSection(app, key, pdfPath)
            
            key = string(key);
            
            
            switch key
                
                case "QC_SUMMARY"
                    
                    app.exportReportQCSummary(pdfPath);
                    
                    
                case "QC_ISSUES"
                    
                    app.exportReportQCIssues(pdfPath);
                    
                    
                case "PREPROCESS_WORKFLOW"
                    
                    app.exportReportPreprocessWorkflow(pdfPath);
                    
                    
                case "PREPROCESS_HISTORY"
                    
                    app.exportReportPreprocessHistory(pdfPath);
                    
                    
                case "MEAN_SPECTRA"
                    
                    app.appendReportAxesPage( ...
                        pdfPath, ...
                        app.UIAxes2, ...
                        "Mean spectra analysis");
                    
                    
                case "RATIO_RESULTS"
                    
                    ratioReportTable = ...
                        app.makeRatioReportTable(20);
                    
                    if isempty(ratioReportTable)
                        
                        app.appendReportTextPage( ...
                            pdfPath, ...
                            'Ratio screening results', ...
                            { ...
                            'No ratio result is available.'}, ...
                            true);
                        
                    else
                        
                        app.appendRatioReportTablePages( ...
                            pdfPath, ...
                            ratioReportTable, ...
                            'Ratio screening results', ...
                            true);
                        
                    end
                    
                    
                case "PCA_SCORE"
                    
                    app.appendReportAxesPage( ...
                        pdfPath, ...
                        app.UIAxes4, ...
                        "Principal component analysis - score plot");
                    
                    
                case "PCA_LOADING"
                    
                    app.appendReportAxesPage( ...
                        pdfPath, ...
                        app.UIAxes5, ...
                        "Principal component analysis - loadings");
                    
                    
                case "CLASS_SUMMARY"
                    
                    app.exportReportClassificationSummary(pdfPath);
                    
                    
                case "CLASS_SCORE"
                    
                    app.appendReportAxesPage( ...
                        pdfPath, ...
                        app.UIAxes4_2, ...
                        app.classMethod + " - score plot");
                    
                    
                case "CLASS_LOADING"
                    
                    if startsWith(app.classMethod, "SVM")
                        
                        pageTitle = ...
                            app.classMethod + " - spectral importance";
                        
                    else
                        
                        pageTitle = ...
                            app.classMethod + " - spectral coefficients / loadings";
                        
                    end
                    
                    
                    app.appendReportAxesPage( ...
                        pdfPath, ...
                        app.UIAxes5_2, ...
                        pageTitle);
                    
                    
                case "FULL_AUDIT"
                    
                    app.appendReportTablePages( ...
                        pdfPath, ...
                        app.ActivityHistory, ...
                        "Full activity history");
                    
            end
            
        end

        function appendReportCoverPage(app, pdfPath)
            
            lines = strings(0,1);
            
            
            lines(end+1,1) = ...
                "RAMAN SPECTROSCOPY PROCESSING AND ANALYSIS REPORT";
            
            lines(end+1,1) = "";
            
            lines(end+1,1) = ...
                "Generated: " + ...
                string(datetime('now', ...
                'Format', ...
                'yyyy-MM-dd HH:mm:ss'));
            
            
            if strlength(app.homeUser) > 0
                
                lines(end+1,1) = ...
                    "User: " + app.homeUser;
                
            end
            
            
            if strlength(app.projectName) > 0
                
                lines(end+1,1) = ...
                    "Project: " + string(app.projectName);
                
            end
            
            
            lines(end+1,1) = "";
            
            
            % -------------------------------------------------------------
            % Data overview
            % -------------------------------------------------------------
            if ~isempty(app.singleQC) && ...
                    isstruct(app.singleQC) && ...
                    isfield(app.singleQC, 'TotalSpectra')
                
                lines(end+1,1) = ...
                    "DATA OVERVIEW";
                
                lines(end+1,1) = ...
                    "Groups: " + ...
                    string(app.singleQC.TotalGroups);
                
                lines(end+1,1) = ...
                    "Spectra: " + ...
                    string(app.singleQC.TotalSpectra);
                
                lines(end+1,1) = ...
                    "QC status: " + ...
                    string(app.singleQC.Status);
                
            end
            
            
            lines(end+1,1) = "";
            
            
            % -------------------------------------------------------------
            % Preprocessing overview
            % -------------------------------------------------------------
            if strlength(app.processHistory) > 0
                
                lines(end+1,1) = ...
                    "PROCESSING WORKFLOW";
                
                lines(end+1,1) = ...
                    string(app.processHistory);
                
            end
            
            
            lines(end+1,1) = "";
            
            
            % -------------------------------------------------------------
            % Analysis overview
            % -------------------------------------------------------------
            analysisDone = strings(0,1);
            
            
            if ~isempty(app.plottedMeanSpectra)
                analysisDone(end+1,1) = "Mean spectra";
            end
            
            
            if ~isempty(app.ratioScreeningTop)
                analysisDone(end+1,1) = "Ratio screening";
            end
            
            
            if ~isempty(app.pcaScores)
                analysisDone(end+1,1) = "PCA";
            end
            
            
            if strlength(app.classMethod) > 0
                analysisDone(end+1,1) = app.classMethod;
            end
            
            
            if ~isempty(analysisDone)
                
                lines(end+1,1) = ...
                    "ANALYSIS MODULES";
                
                for i = 1:numel(analysisDone)
                    
                    lines(end+1,1) = ...
                        "- " + analysisDone(i);
                    
                end
                
            end
            
            
            app.appendReportTextPages( ...
                pdfPath, ...
                "Raman spectroscopy report", ...
                lines);
            
        end


        function exportReportQCSummary(app, pdfPath)
            
            qc = app.singleQC;
            
            lines = strings(0,1);
            
            
            lines(end+1,1) = ...
                "Overall QC status: " + string(qc.Status);
            
            lines(end+1,1) = "";
            
            lines(end+1,1) = ...
                sprintf( ...
                "Groups: %d", ...
                qc.TotalGroups);
            
            lines(end+1,1) = ...
                sprintf( ...
                "Spectra: %d", ...
                qc.TotalSpectra);
            
            
            lines(end+1,1) = "";
            
            lines(end+1,1) = ...
                "QUALITY CONTROL MODULES";
            
            lines(end+1,1) = ...
                "Metadata: " + string(qc.MetadataStatus);
            
            lines(end+1,1) = ...
                "Wavenumber consistency: " + ...
                string(qc.WavenumberStatus);
            
            lines(end+1,1) = ...
                "Raw SNR: " + string(qc.SNRStatus);
            
            
            lines(end+1,1) = "";
            
            lines(end+1,1) = ...
                "WAVENUMBER SUMMARY";
            
            lines(end+1,1) = sprintf( ...
                "Reference points: %d", ...
                qc.WnReferenceLength);
            
            lines(end+1,1) = sprintf( ...
                "Range: %.2f - %.2f cm^-1", ...
                qc.WnReferenceMin, ...
                qc.WnReferenceMax);
            
            lines(end+1,1) = sprintf( ...
                "Axis mismatch spectra: %d", ...
                qc.WnMismatchCount);
            
            lines(end+1,1) = sprintf( ...
                "Linear shifts: %d", ...
                qc.WnLinearShiftCount);
            
            lines(end+1,1) = sprintf( ...
                "Nonlinear shifts: %d", ...
                qc.WnNonLinearShiftCount);
            
            
            lines(end+1,1) = "";
            
            lines(end+1,1) = ...
                "RAW SNR SUMMARY";
            
            lines(end+1,1) = sprintf( ...
                "Threshold: %.2f", ...
                qc.LowSNRThreshold);
            
            lines(end+1,1) = sprintf( ...
                "Median SNR: %.3f", ...
                qc.MedianSNR);
            
            lines(end+1,1) = sprintf( ...
                "Minimum SNR: %.3f", ...
                qc.MinSNR);
            
            lines(end+1,1) = sprintf( ...
                "Maximum SNR: %.3f", ...
                qc.MaxSNR);
            
            lines(end+1,1) = sprintf( ...
                "Low or invalid SNR spectra: %d", ...
                qc.LowSNRSpectra);
            
            
            app.appendReportTextPages( ...
                pdfPath, ...
                "Quality control summary", ...
                lines);
            
        end


        function exportReportQCIssues(app, pdfPath)
            
            qc = app.singleQC;
            
            lines = strings(0,1);
            
            
            lines(end+1,1) = ...
                "Overall QC status: " + ...
                string(qc.Status);
            
            
            lines(end+1,1) = "";
            
            
            % -------------------------------------------------------------
            % Issues
            % -------------------------------------------------------------
            lines(end+1,1) = "DETECTED ISSUES";
            
            
            if ~isfield(qc, 'Issues') || isempty(qc.Issues)
                
                lines(end+1,1) = ...
                    "No QC issue was recorded.";
                
            else
                
                issues = string(qc.Issues(:));
                issues = strtrim(issues);
                issues = issues(issues ~= "");
                
                
                if isempty(issues)
                    
                    lines(end+1,1) = ...
                        "No QC issue was recorded.";
                    
                else
                    
                    for i = 1:numel(issues)
                        
                        lines(end+1,1) = ...
                            sprintf( ...
                            "%d. %s", ...
                            i, ...
                            issues(i));
                        
                    end
                    
                end
                
            end
            
            
            % -------------------------------------------------------------
            % User decisions
            % -------------------------------------------------------------
            lines(end+1,1) = "";
            lines(end+1,1) = "USER DECISIONS";
            
            
            if app.singleWNAccepted
                
                lines(end+1,1) = ...
                    "WN inconsistency: ACCEPTED";
                
                if strlength(app.singleWNAcceptNote) > 0
                    
                    lines(end+1,1) = ...
                        "WN note: " + ...
                        app.singleWNAcceptNote;
                    
                end
                
            else
                
                lines(end+1,1) = ...
                    "WN inconsistency acceptance: No user acceptance recorded.";
                
            end
            
            
            if app.singleSNRAccepted
                
                lines(end+1,1) = ...
                    "Low SNR warning: ACCEPTED";
                
                if strlength(app.singleSNRAcceptNote) > 0
                    
                    lines(end+1,1) = ...
                        "SNR note: " + ...
                        app.singleSNRAcceptNote;
                    
                end
                
            else
                
                lines(end+1,1) = ...
                    "Low SNR acceptance: No user acceptance recorded.";
                
            end
            
            
            app.appendReportTextPages( ...
                pdfPath, ...
                "QC issues and decisions", ...
                lines);
            
        end


        function exportReportPreprocessWorkflow(app, pdfPath)
            
            lines = strings(0,1);
            
            
            lines(end+1,1) = ...
                "CURRENT PROCESSING PIPELINE";
            
            lines(end+1,1) = "";
            
            lines(end+1,1) = ...
                string(app.processHistory);
            
            
            lines(end+1,1) = "";
            
            
            if strlength(app.analysisSource) > 0
                
                lines(end+1,1) = ...
                    "Analysis source: " + ...
                    string(app.analysisSource);
                
            end
            
            
            if strlength(app.analysisHistory) > 0
                
                lines(end+1,1) = ...
                    "Analysis data history: " + ...
                    string(app.analysisHistory);
                
            end
            
            
            if ~isempty(app.wn)
                
                lines(end+1,1) = "";
                
                lines(end+1,1) = sprintf( ...
                    "Current WN points: %d", ...
                    numel(app.wn));
                
                lines(end+1,1) = sprintf( ...
                    "Current WN range: %.2f - %.2f cm^-1", ...
                    min(app.wn), ...
                    max(app.wn));
                
            end
            
            
            if ~isempty(app.spectra)
                
                lines(end+1,1) = sprintf( ...
                    "Current spectra: %d", ...
                    size(app.spectra,2));
                
            end
            
            
            app.appendReportTextPages( ...
                pdfPath, ...
                "Preprocessing workflow", ...
                lines);
            
        end
        
        
        function exportReportPreprocessHistory(app, pdfPath)
            
            if isempty(app.ActivityHistory)
                return
            end
            
            
            T = app.ActivityHistory;
            
            
            % Keep activities associated with processing or child apps.
            stageText = lower(string(T.Stage));
            actionText = lower(string(T.Action));
            
            
            mask = ...
                contains(stageText, "preprocess") | ...
                contains(stageText, "checkpoint") | ...
                contains(actionText, "cosmic") | ...
                contains(actionText, "baseline") | ...
                contains(actionText, "smooth") | ...
                contains(actionText, "define") | ...
                contains(actionText, "child");
            
            
            Tsub = T(mask,:);
            
            
            if isempty(Tsub)
                
                % Fall back to full history if no explicit preprocess entries
                Tsub = T;
                
            end
            
            
            app.appendReportTablePages( ...
                pdfPath, ...
                Tsub, ...
                "Preprocessing activity history");
            
        end


        function exportReportClassificationSummary(app, pdfPath)
            
            lines = strings(0,1);
            
            
            lines(end+1,1) = ...
                "Method: " + app.classMethod;
            
            
            if isfinite(app.classAccuracy)
                
                lines(end+1,1) = ...
                    sprintf( ...
                    "Accuracy: %.2f%%", ...
                    app.classAccuracy);
                
            end
            
            
            if strlength(app.classNormMethod) > 0
                
                lines(end+1,1) = ...
                    "Normalization: " + ...
                    app.classNormMethod;
                
            end
            
            
            lines(end+1,1) = "";
            
            
            if ~isempty(app.classGroupNames)
                
                lines(end+1,1) = "GROUPS";
                
                for i = 1:numel(app.classGroupNames)
                    
                    gName = app.classGroupNames(i);
                    
                    nGroup = nnz( ...
                        app.classGroupLabels == gName);
                    
                    
                    lines(end+1,1) = sprintf( ...
                        "- %s: %d spectra", ...
                        gName, ...
                        nGroup);
                    
                end
                
            end
            
            
            lines(end+1,1) = "";
            
            
            if startsWith(app.classMethod, "SVM")
                
                lines(end+1,1) = ...
                    "MODEL INTERPRETATION";
                
                lines(end+1,1) = ...
                    "The spectral importance plot represents relative predictor importance.";
                
                lines(end+1,1) = ...
                    "It should not be interpreted as a conventional PCA or PLS loading vector.";
                
            end
            
            
            app.appendReportTextPages( ...
                pdfPath, ...
                "Classification summary", ...
                lines);
            
            
            % -------------------------------------------------------------
            % Confusion matrix
            % -------------------------------------------------------------
            if ~isempty(app.classConfusion)
                
                app.appendReportTablePages( ...
                    pdfPath, ...
                    app.classConfusion, ...
                    app.classMethod + ...
                    " - confusion matrix");
                
            end
            
        end


        function appendReportTextPages(app, pdfPath, pageTitle, lines)
            
            lines = string(lines(:));
            
            maxLines = 34;
            
            nPages = ceil(numel(lines) / maxLines);
            
            if nPages < 1
                nPages = 1;
            end
            
            
            for p = 1:nPages
                
                idx1 = (p-1)*maxLines + 1;
                
                idx2 = min( ...
                    p*maxLines, ...
                    numel(lines));
                
                
                if isempty(lines)
                    
                    pageLines = "";
                    
                else
                    
                    pageLines = lines(idx1:idx2);
                    
                end
                
                
                f = figure( ...
                    'Visible', 'off', ...
                    'Color', 'white', ...
                    'Units', 'centimeters', ...
                    'Position', [2 2 21 29.7], ...
                    'PaperPositionMode', 'auto');
                
                
                cleaner = onCleanup(@() delete(f));
                
                
                % =========================================================
                % IMPORTANT:
                % Make this temporary figure current before annotation()
                % =========================================================
                figure(f);
                
                
                % =========================================================
                % TITLE
                % =========================================================
                annotation( ...
                    'textbox', ...
                    [0.08 0.90 0.84 0.06], ...
                    'String', char(pageTitle), ...
                    'EdgeColor', 'none', ...
                    'FontName', 'Arial', ...
                    'FontSize', 18, ...
                    'FontWeight', 'bold', ...
                    'Interpreter', 'none');
                
                
                % =========================================================
                % BODY
                % =========================================================
                annotation( ...
                    'textbox', ...
                    [0.08 0.08 0.84 0.80], ...
                    'String', cellstr(pageLines), ...
                    'EdgeColor', 'none', ...
                    'FontName', 'Arial', ...
                    'FontSize', 10, ...
                    'VerticalAlignment', 'top', ...
                    'Interpreter', 'none');
                
                
                % =========================================================
                % PAGE NUMBER
                % =========================================================
                if nPages > 1
                    
                    annotation( ...
                        'textbox', ...
                        [0.75 0.02 0.18 0.03], ...
                        'String', ...
                        sprintf('Page %d / %d', p, nPages), ...
                        'EdgeColor', 'none', ...
                        'HorizontalAlignment', 'right', ...
                        'FontSize', 8);
                    
                end
                
                
                drawnow;
                
                
                app.appendFigureToReportPDF( ...
                    f, ...
                    pdfPath);
                
            end
            
        end


        function appendReportAxesPage(app, pdfPath, sourceAx, pageTitle)
            
            if isempty(sourceAx) || ~isvalid(sourceAx)
                return
            end
            
            
            if isempty(sourceAx.Children)
                return
            end
            
            
            f = figure( ...
                'Visible', 'off', ...
                'Color', 'white', ...
                'Units', 'centimeters', ...
                'Position', [2 2 21 29.7], ...
                'Renderer', 'painters');
            
            
            cleaner = onCleanup(@() delete(f));
            
            
            % =============================================================
            % Make report figure current
            % =============================================================
            figure(f);
            
            
            % =============================================================
            % PAGE TITLE
            % =============================================================
            annotation( ...
                'textbox', ...
                [0.08 0.91 0.84 0.05], ...
                'String', char(pageTitle), ...
                'EdgeColor', 'none', ...
                'FontName', 'Arial', ...
                'FontSize', 17, ...
                'FontWeight', 'bold', ...
                'Interpreter', 'none');
            
            
            % =============================================================
            % PUBLICATION AXIS
            % =============================================================
            targetAx = axes( ...
                'Parent', f, ...
                'Units', 'normalized', ...
                'Position', [0.13 0.24 0.78 0.58]);
            
            
            copyobj( ...
                flip(sourceAx.Children), ...
                targetAx);
            
            
            try
                targetAx.XLim = sourceAx.XLim;
            catch
            end
            
            
            try
                targetAx.YLim = sourceAx.YLim;
            catch
            end
            
            
            try
                targetAx.XScale = sourceAx.XScale;
                targetAx.YScale = sourceAx.YScale;
            catch
            end
            
            
            try
                targetAx.XDir = sourceAx.XDir;
                targetAx.YDir = sourceAx.YDir;
            catch
            end
            
            
            try
                targetAx.CLim = sourceAx.CLim;
            catch
            end
            
            
            try
                colormap(targetAx, colormap(sourceAx));
            catch
            end
            
            
            targetAx.Box = 'on';
            targetAx.FontName = 'Arial';
            targetAx.FontSize = 10;
            targetAx.LineWidth = 0.8;
            targetAx.TickDir = 'out';
            
            
            xlabel( ...
                targetAx, ...
                sourceAx.XLabel.String, ...
                'Interpreter', sourceAx.XLabel.Interpreter, ...
                'FontName', 'Arial', ...
                'FontSize', 11);
            
            
            ylabel( ...
                targetAx, ...
                sourceAx.YLabel.String, ...
                'Interpreter', sourceAx.YLabel.Interpreter, ...
                'FontName', 'Arial', ...
                'FontSize', 11);
            
            
            title( ...
                targetAx, ...
                sourceAx.Title.String, ...
                'Interpreter', sourceAx.Title.Interpreter, ...
                'FontName', 'Arial', ...
                'FontSize', 12, ...
                'FontWeight', 'normal');
            
            
            % =============================================================
            % LEGEND
            % =============================================================
            try
                
                h = findobj( ...
                    targetAx.Children, ...
                    '-property', ...
                    'DisplayName');
                
                
                dn = string(get(h, 'DisplayName'));
                
                
                if any(strlength(dn) > 0)
                    
                    legend( ...
                        targetAx, ...
                        'show', ...
                        'Location', 'best', ...
                        'Box', 'off', ...
                        'FontSize', 9);
                    
                end
                
            catch
            end
            
            
            drawnow;
            
            
            app.appendFigureToReportPDF( ...
                f, ...
                pdfPath);
            
        end


        function appendReportTablePages(app, pdfPath, T, pageTitle)
            
            if isempty(T)
                return
            end
            
            
            if ~istable(T)
                error('Report table input must be a MATLAB table.');
            end
            
            
            rowsPerPage = 18;
            
            nRows = height(T);
            
            nPages = ceil(nRows / rowsPerPage);
            
            
            for p = 1:nPages
                
                r1 = (p-1)*rowsPerPage + 1;
                
                r2 = min( ...
                    p*rowsPerPage, ...
                    nRows);
                
                
                Tpage = T(r1:r2,:);
                
                
                f = figure( ...
                    'Visible', 'off', ...
                    'Color', 'white', ...
                    'Units', 'centimeters', ...
                    'Position', [2 2 21 29.7]);
                
                
                cleaner = onCleanup(@() delete(f));
                
                
                % =========================================================
                % Make figure current
                % =========================================================
                figure(f);
                
                
                annotation( ...
                    'textbox', ...
                    [0.06 0.91 0.88 0.05], ...
                    'String', char(pageTitle), ...
                    'EdgeColor', 'none', ...
                    'FontName', 'Arial', ...
                    'FontSize', 17, ...
                    'FontWeight', 'bold', ...
                    'Interpreter', 'none');
                
                
                uit = uitable( ...
                    f, ...
                    'Data', Tpage, ...
                    'Units', 'normalized', ...
                    'Position', [0.05 0.10 0.90 0.77]);
                
                
                uit.FontName = 'Arial';
                uit.FontSize = 8;
                
                
                if nPages > 1
                    
                    annotation( ...
                        'textbox', ...
                        [0.75 0.02 0.20 0.03], ...
                        'String', ...
                        sprintf('Page %d / %d', p, nPages), ...
                        'EdgeColor', 'none', ...
                        'HorizontalAlignment', 'right', ...
                        'FontSize', 8);
                    
                end
                
                
                drawnow;
                
                
                app.appendFigureToReportPDF( ...
                    f, ...
                    pdfPath);
                
            end
            
        end
        
        
        function appendFigureToReportPDF(app, f, pdfPath)
            
            drawnow;
            
            
            if isfile(pdfPath)
                
                exportgraphics( ...
                    f, ...
                    pdfPath, ...
                    'ContentType', ...
                    'vector', ...
                    'BackgroundColor', ...
                    'white', ...
                    'Append', ...
                    true);
                
            else
                
                exportgraphics( ...
                    f, ...
                    pdfPath, ...
                    'ContentType', ...
                    'vector', ...
                    'BackgroundColor', ...
                    'white');
                
            end
            
        end


        function safeDeleteReportDialog(app, d)
            
            try
                
                if ~isempty(d) && isvalid(d)
                    delete(d);
                end
                
            catch
            end
            
        end
        
        
        function safeName = makeSafeSessionName(app, sessionName)
            safeName = string(sessionName);
            % Remove leading/trailing spaces
            safeName = strtrim(safeName);
            % Replace spaces by underscore
            safeName = regexprep(safeName, '\s+', '_');
            % Remove invalid file/folder characters
            safeName = regexprep(safeName, '[<>:"/\\|?*]', '_');
            % Replace other unusual characters
            safeName = regexprep(safeName, '[^\w\-]', '_');
            % Collapse repeated underscores
            safeName = regexprep(safeName, '_+', '_');
            % Remove underscore at beginning/end
            safeName = regexprep(safeName, '^_+|_+$', '');
            % Fallback
            if strlength(safeName) == 0
                safeName = "Untitled";
            end
        end
        
        function ok = requestSingleSessionName(app)
            
            ok = false;
            
            answer = inputdlg( ...
                {sprintf([ ...
                'Enter a name for this analysis session.\n\n' ...
                'Example:\n' ...
                'MouseTibia_Proximal_Control'])}, ...
                'New analysis session', ...
                [1 60], ...
                {'Untitled'});
            
            if isempty(answer)
                return
            end
            
            sessionName = strtrim(string(answer{1}));
            
            if strlength(sessionName) == 0
                uialert( ...
                    app.UIFigure, ...
                    'Session name cannot be empty.', ...
                    'Invalid session name');
                return
            end
            
            app.singleSessionName = ...
                app.makeSafeSessionName(sessionName);
            
            app.singleSessionID = string( ...
                datetime('now', ...
                'Format', ...
                'yyyyMMdd_HHmmss'));
            
            ok = true;
            
        end
        
        
        function denominator = computeReferenceFeature( ...
                app, ...
                X, ...
                wn, ...
                peakInfo, ...
                featureType)
            
            %#ok<INUSD>
            
            % X:
            % rows    = spectra
            % columns = Raman variables
            
            wn = double(wn(:));
            X = double(X);
            
            nSpectra = size(X,1);
            
            denominator = ...
                nan(nSpectra,1);
            
            
            % =========================================================
            % Validate matrix orientation
            % =========================================================
            if size(X,2) ~= numel(wn)
                
                error( ...
                    ['Reference feature calculation expected X as ' ...
                    'spectra x wavenumbers.']);
                
            end
            
            
            % =========================================================
            % Peak definition
            % =========================================================
            lo = double(peakInfo.LowWN(1));
            hi = double(peakInfo.HighWN(1));
            center = double(peakInfo.CenterWN(1));
            
            lo2 = min(lo, hi);
            hi2 = max(lo, hi);
            
            
            % =========================================================
            % EXACT INTENSITY
            % =========================================================
            if string(featureType) == "Exact intensity"
                
                [~, idxCenter] = ...
                    min(abs(wn - center));
                
                denominator = ...
                    X(:,idxCenter);
                
                return
                
            end
            
            
            % =========================================================
            % AREA
            % =========================================================
            if string(featureType) == "Area"
                
                mask = ...
                    wn >= lo2 & ...
                    wn <= hi2;
                
                if nnz(mask) < 2
                    
                    error( ...
                        ['Reference peak %.1f-%.1f cm-1 contains ' ...
                        'too few spectral points.'], ...
                        lo2, ...
                        hi2);
                    
                end
                
                
                wnBand = ...
                    wn(mask);
                
                XBand = ...
                    X(:,mask);
                
                
                % ---------------------------------------------------------
                % IMPORTANT:
                % trapz must integrate on ascending axis
                % ---------------------------------------------------------
                [wnBand, sortIdx] = ...
                    sort(wnBand, 'ascend');
                
                XBand = ...
                    XBand(:,sortIdx);
                
                
                denominator = ...
                    trapz( ...
                    wnBand, ...
                    XBand, ...
                    2);
                
                return
                
            end
            
            
            % =========================================================
            % Unsupported feature
            % =========================================================
            error( ...
                'Unsupported reference feature type: %s', ...
                string(featureType));
            
        end


        function [ ...
                Xscaled, ...
                groupLabels, ...
                groupNames, ...
                spectrumNames, ...
                referenceInfo] = ...
                buildReferenceScaledPCAInput(app)
            
            
            % =========================================================
            % Require Ratio peak selection
            % =========================================================
            if isempty(app.ratioScreeningSelectedPeaks)
                
                error([ ...
                    'No selected Ratio peaks are available.' newline ...
                    'Please run Ratio calculation first.']);
                
            end
            
            
            peakTable = ...
                app.ratioScreeningSelectedPeaks;
            
            
            % =========================================================
            % Build denominator list
            % =========================================================
            peakList = strings(height(peakTable),1);
            
            for i = 1:height(peakTable)
                
                peakList(i) = sprintf( ...
                    '%.1f-%.1f cm-1 | center %.1f | %s', ...
                    peakTable.LowWN(i), ...
                    peakTable.HighWN(i), ...
                    peakTable.CenterWN(i), ...
                    string(peakTable.Interpretation(i)));
                
            end
            
            
            % =========================================================
            % Select ONE denominator
            % =========================================================
            [sel, ok] = listdlg( ...
                'PromptString', ...
                'Select ONE reference denominator:', ...
                'SelectionMode', ...
                'single', ...
                'ListString', ...
                cellstr(peakList), ...
                'InitialValue', ...
                1, ...
                'ListSize', ...
                [520 320]);
            
            
            if ~ok || isempty(sel)
                
                Xscaled = [];
                groupLabels = strings(0,1);
                groupNames = strings(0,1);
                spectrumNames = strings(0,1);
                referenceInfo = struct();
                
                return
                
            end
            
            
            peakInfo = peakTable(sel,:);
            
            
            % =========================================================
            % Select denominator feature
            % =========================================================
            featureChoice = questdlg( ...
                'How should the denominator be calculated?', ...
                'Reference feature', ...
                'Area', ...
                'Exact intensity', ...
                'Area');
            
            
            if isempty(featureChoice)
                
                Xscaled = [];
                groupLabels = strings(0,1);
                groupNames = strings(0,1);
                spectrumNames = strings(0,1);
                referenceInfo = struct();
                
                return
                
            end
            
            
            featureType = string(featureChoice);
            
            
            % =========================================================
            % Get ordinary spectral PCA input
            % =========================================================
            [ ...
                X, ...
                groupLabels, ...
                groupNames, ...
                spectrumNames] = ...
                app.buildPCAInputFromLabeledGroups();
            
            
            wn = app.analysisWn(:);
            
            
            % =========================================================
            % Calculate denominator for each spectrum
            % =========================================================
            denominator = ...
                app.computeReferenceFeature( ...
                X, ...
                wn, ...
                peakInfo, ...
                featureType);
            
            
            % =========================================================
            % Reject invalid denominator
            % =========================================================
            finiteDen = ...
                denominator(isfinite(denominator));
            
            if isempty(finiteDen)
                
                error( ...
                    'All denominator values are invalid.');
                
            end
            
            
            scaleRef = max(abs(finiteDen));
            
            if scaleRef == 0
                scaleRef = 1;
            end
            
            
            tolerance = ...
                max(eps, 1e-12 * scaleRef);
            
            
            valid = ...
                isfinite(denominator) & ...
                abs(denominator) > tolerance;
            
            
            if nnz(valid) < 2
                
                error( ...
                    'Too few valid spectra after denominator validation.');
                
            end
            
            
            % =========================================================
            % Keep valid observations only
            % =========================================================
            X = X(valid,:);
            
            denominator = ...
                denominator(valid);
            
            groupLabels = ...
                groupLabels(valid);
            
            spectrumNames = ...
                spectrumNames(valid);
            
            
            % =========================================================
            % Scale whole spectrum
            % =========================================================
            Xscaled = ...
                X ./ denominator;
            
            
            % =========================================================
            % Save reference information
            % =========================================================
            referenceInfo = struct();
            
            referenceInfo.Peak = peakInfo;
            
            referenceInfo.FeatureType = ...
                featureType;
            
            referenceInfo.Values = ...
                denominator;
            
            referenceInfo.Wavenumber = ...
                wn;
            
        end


        function map2D = ...
                computeSpatialRatioDefinitionMap( ...
                app, ...
                cubeData, ...
                wn, ...
                def)
            
            def = ...
                app.normalizeRatioDefinitionRow(def);
            
            
            [vars, peakIDs] = ...
                app.parseRatioVariableMap( ...
                def.VariableMap);
            
            
            if isempty(vars)
                
                error( ...
                    'Ratio recipe has no variable mapping.');
                
            end
            
            
            valueMaps = ...
                cell(numel(vars),1);
            
            
            for k = 1:numel(vars)
                
                valueMaps{k} = ...
                    app.computeSpatialRecipePeakFeature( ...
                    cubeData, ...
                    wn, ...
                    peakIDs(k), ...
                    def.FeatureType);
                
            end
            
            
            map2D = ...
                app.evaluateRatioRecipeExpression( ...
                def.Formula, ...
                vars, ...
                valueMaps);
            
            
            map2D(~isfinite(map2D)) = ...
                NaN;
            
        end


        function savePCAResultsBundle(app)
            
            if isempty(app.pcaScores) || ...
                    isempty(app.pcaLoadings)
                
                return
                
            end
            
            
            % =========================================================
            % Output folder
            % =========================================================
            outFolder = ...
                app.getProjectSubFolder( ...
                "Results", ...
                "PCA");
            
            
            if strlength(string(outFolder)) == 0
                return
            end
            
            
            timestamp = char(datetime( ...
                'now', ...
                'Format', ...
                'yyyy-MM-dd_HH-mm-ss'));
            
            
            % =========================================================
            % 1. Scores table
            % =========================================================
            nPC = size(app.pcaScores,2);
            
            pcNames = ...
                "PC" + string(1:nPC);
            
            Tscores = array2table( ...
                app.pcaScores, ...
                'VariableNames', ...
                cellstr(pcNames));
            
            
            Tscores = addvars( ...
                Tscores, ...
                string(app.pcaSpectrumNames(:)), ...
                string(app.pcaGroupLabels(:)), ...
                'Before', ...
                1, ...
                'NewVariableNames', ...
                {'SpectrumName','Group'});
            
            
            writetable( ...
                Tscores, ...
                fullfile( ...
                outFolder, ...
                ['pca_scores_' ...
                timestamp '.txt']), ...
                'Delimiter', '\t');
            
            
            % =========================================================
            % 2. Loadings table
            % =========================================================
            Tloadings = array2table( ...
                app.pcaLoadings, ...
                'VariableNames', ...
                cellstr(pcNames));
            
            
            if app.pcaInputType == "Ratios"
                
                variableLabel = ...
                    string(app.pcaVariableNames(:));
                
                Tloadings = addvars( ...
                    Tloadings, ...
                    variableLabel, ...
                    'Before', ...
                    1, ...
                    'NewVariableNames', ...
                    'Variable');
                
            else
                
                Wavenumber = ...
                    app.pcaWn(:);
                
                Tloadings = addvars( ...
                    Tloadings, ...
                    Wavenumber, ...
                    'Before', ...
                    1);
                
            end
            
            
            writetable( ...
                Tloadings, ...
                fullfile( ...
                outFolder, ...
                ['pca_loadings_' ...
                timestamp '.txt']), ...
                'Delimiter', '\t');
            
            
            % =========================================================
            % 3. Explained variance
            % =========================================================
            PC = ...
                string("PC") + ...
                string((1:numel(app.pcaExplained))');
            
            ExplainedPercent = ...
                app.pcaExplained(:);
            
            Texplained = table( ...
                PC, ...
                ExplainedPercent);
            
            
            writetable( ...
                Texplained, ...
                fullfile( ...
                outFolder, ...
                ['pca_explained_variance_' ...
                timestamp '.txt']), ...
                'Delimiter', '\t');
            
            
            % =========================================================
            % 4. Reference denominator values
            % =========================================================
            if app.pcaInputType == ...
                    "Reference-scaled spectra" && ...
                    ~isempty(app.pcaReferenceValues)
                
                Tref = table( ...
                    string(app.pcaSpectrumNames(:)), ...
                    string(app.pcaGroupLabels(:)), ...
                    app.pcaReferenceValues(:), ...
                    'VariableNames', ...
                    { ...
                    'SpectrumName', ...
                    'Group', ...
                    'ReferenceValue'});
                
                
                writetable( ...
                    Tref, ...
                    fullfile( ...
                    outFolder, ...
                    ['reference_values_' ...
                    timestamp '.txt']), ...
                    'Delimiter', '\t');
                
            end
            
            
            % =========================================================
            % 5. Full PCA bundle
            % =========================================================
            PCAExport = struct();
            
            PCAExport.savedAt = ...
                datetime('now');
            
            PCAExport.inputType = ...
                app.pcaInputType;
            
            PCAExport.normMethod = ...
                app.pcaNormMethod;
            
            PCAExport.sourceX = ...
                app.pcaSourceX;
            
            PCAExport.finalPCAInput = ...
                app.pcaX;
            
            PCAExport.wavenumber = ...
                app.pcaWn;
            
            PCAExport.variableNames = ...
                app.pcaVariableNames;
            
            PCAExport.scores = ...
                app.pcaScores;
            
            PCAExport.loadings = ...
                app.pcaLoadings;
            
            PCAExport.explained = ...
                app.pcaExplained;
            
            PCAExport.groupLabels = ...
                app.pcaGroupLabels;
            
            PCAExport.groupNames = ...
                app.pcaGroupNames;
            
            PCAExport.spectrumNames = ...
                app.pcaSpectrumNames;
            
            
            % Reference-scaled information
            PCAExport.referenceValues = ...
                app.pcaReferenceValues;
            
            PCAExport.referenceScaledX = ...
                app.pcaReferenceScaledX;
            
            PCAExport.referenceDefinition = ...
                app.pcaReferenceDefinition;
            
            PCAExport.referenceFeatureType = ...
                app.pcaReferenceFeatureType;
            
            
            save( ...
                fullfile( ...
                outFolder, ...
                ['pca_bundle_' ...
                timestamp '.mat']), ...
                'PCAExport', ...
                '-v7.3');
            
        end
        
        
        function cp = createEmptyPreprocessCheckpoint(app)
            
            % Standard schema shared by every preprocessing stage.
            cp = struct( ...
                'SchemaVersion', "1.0", ...
                'Stage', "", ...
                'Completed', false, ...
                'InputSpectra', [], ...
                'InputCube', [], ...
                'OutputSpectra', [], ...
                'OutputCube', [], ...
                'InputHistory', "", ...
                'OutputHistory', "", ...
                'ActionMode', "", ...
                'AppliedAt', "", ...
                'ResultStatus', "", ...
                'DataChanged', false, ...
                'ResultInfo', struct());
            
        end
        
        
        function featureMap = ...
                computeSpatialRecipePeakFeature( ...
                app, ...
                cubeData, ...
                wn, ...
                peakID, ...
                featureType)
            
            % =============================================================
            % COMPUTE ONE PEAK FEATURE FOR A SPATIAL CUBE
            %
            % Peak IDs refer to the canonical shared peak library.
            % =============================================================
            
            
            % =============================================================
            % 1. CANONICAL PEAK LIBRARY
            % =============================================================
            
            peakLibrary = ...
                app.getCanonicalPeakLibrary();
            
            
            if isempty(peakLibrary)
                
                error( ...
                    ['No valid peak library is available for this spatial ' ...
                    'ratio / score recipe.']);
                
            end
            
            
            % =============================================================
            % 2. VALIDATE PEAK ID
            % =============================================================
            
            peakID = ...
                round(double(peakID));
            
            
            if ~isfinite(peakID) || ...
                    peakID < 1 || ...
                    peakID > height(peakLibrary)
                
                error( ...
                    'Ratio recipe refers to unavailable peak P%d.', ...
                    peakID);
                
            end
            
            
            peak = ...
                peakLibrary(peakID,:);
            
            
            % =============================================================
            % 3. PEAK METADATA
            % =============================================================
            
            lowWN = ...
                double(peak.LowWN);
            
            
            highWN = ...
                double(peak.HighWN);
            
            
            centerWN = ...
                double(peak.CenterWN);
            
            
            if ~isfinite(lowWN) || ...
                    ~isfinite(highWN) || ...
                    ~isfinite(centerWN)
                
                error( ...
                    'Peak P%d contains invalid WN metadata.', ...
                    peakID);
                
            end
            
            
            featureType = ...
                string(featureType);
            
            
            % =============================================================
            % 4. FEATURE
            % =============================================================
            
            switch featureType
                
                
                % =========================================================
                % AREA
                % =========================================================
                case "Area"
                    
                    featureMap = ...
                        app.integrateSpatialBand( ...
                        cubeData, ...
                        wn, ...
                        [ ...
                        lowWN, ...
                        highWN ...
                        ]);
                    
                    
                    % =========================================================
                    % EXACT INTENSITY
                    % =========================================================
                case { ...
                        "Intensity", ...
                        "Exact intensity"}
                    
                    [~,idx] = ...
                        min( ...
                        abs( ...
                        double(wn(:)) - ...
                        centerWN));
                    
                    featureMap = ...
                        double( ...
                        cubeData(:,:,idx));
                    
                    
                    % =========================================================
                    % 1 / FWHM
                    % =========================================================
                case "1/FWHM"
                    
                    fwhmMap = ...
                        app.spatialFWHMMap( ...
                        cubeData, ...
                        wn, ...
                        [ ...
                        lowWN, ...
                        highWN ...
                        ]);
                    
                    
                    featureMap = ...
                        nan(size(fwhmMap));
                    
                    
                    valid = ...
                        isfinite(fwhmMap) & ...
                        fwhmMap > 0;
                    
                    
                    featureMap(valid) = ...
                        1 ./ ...
                        fwhmMap(valid);
                    
                    
                otherwise
                    
                    error( ...
                        'Unsupported shared ratio feature type: %s', ...
                        featureType);
                    
            end
            
        end


        function [recipeDef, selectionMode, manualBands, ok] = ...
                chooseSpatialRatioRecipe(app)
            
            recipeDef = table();
            
            selectionMode = "";
            
            manualBands = [];
            
            ok = false;
            
            
            app.ensureRatioDefinitionSchema();
            
            
            saved = ...
                app.ratioDefinitions;
            
            
            items = ...
                strings(height(saved)+2,1);
            
            
            for r = 1:height(saved)
                
                if saved.DefinitionType(r) == ...
                        "CompositeRatio"
                    
                    typeText = ...
                        "Score";
                    
                else
                    
                    typeText = ...
                        "Ratio";
                    
                end
                
                
                items(r) = ...
                    sprintf( ...
                    '[Saved %s] %s | %s | %s', ...
                    typeText, ...
                    saved.RatioName(r), ...
                    saved.Formula(r), ...
                    saved.FeatureType(r));
                
            end
            
            
            addIndex = ...
                height(saved)+1;
            
            
            manualIndex = ...
                height(saved)+2;
            
            
            items(addIndex) = ...
                "< Add new ratio / composite score... >";
            
            
            items(manualIndex) = ...
                "< Enter two bands manually... >";
            
            
            [sel, accepted] = ...
                listdlg( ...
                'PromptString', ...
                'Select spatial ratio source:', ...
                'SelectionMode', ...
                'single', ...
                'ListString', ...
                cellstr(items), ...
                'ListSize', ...
                [650 390]);
            
            
            if ~accepted
                return
            end
            
            
            % =============================================================
            % SAVED
            % =============================================================
            
            if sel <= height(saved)
                
                recipeDef = ...
                    saved(sel,:);
                
                selectionMode = ...
                    "Saved recipe";
                
                ok = true;
                
                return
                
            end
            
            
            % =============================================================
            % CREATE NEW
            % =============================================================
            
            if sel == addIndex
                
                [newDef, okNew] = ...
                    app.promptNewRatioDefinition();
                
                
                if ~okNew
                    return
                end
                
                
                [added, messageText] = ...
                    app.addRatioDefinitionInternal( ...
                    newDef);
                
                
                if added
                    
                    app.saveAnalysisMetadata(false);
                    
                end
                
                
                recipeDef = ...
                    app.normalizeRatioDefinitionRow( ...
                    newDef);
                
                
                selectionMode = ...
                    "New saved recipe";
                
                
                msgbox( ...
                    char(messageText), ...
                    'Spatial ratio');
                
                
                ok = true;
                
                return
                
            end
            
            
            % =============================================================
            % MANUAL TWO-BAND RATIO
            % =============================================================
            
            % =============================================================
            % MANUAL TWO-BAND RATIO
            % =============================================================
            
            if sel == manualIndex
                
                answer = ...
                    inputdlg( ...
                    { ...
                    'Numerator start:', ...
                    'Numerator end:', ...
                    'Denominator start:', ...
                    'Denominator end:'}, ...
                    'Manual spatial ratio', ...
                    [1 50], ...
                    {'1440','1470','1640','1680'});
                
                
                if isempty(answer)
                    return
                end
                
                
                vals = ...
                    cellfun( ...
                    @str2double, ...
                    answer);
                
                
                % Force numeric column vector.
                vals = ...
                    double(vals(:));
                
                
                if numel(vals) ~= 4 || ...
                        any(~isfinite(vals))
                    
                    uialert( ...
                        app.UIFigure, ...
                        'Invalid manual ratio range.', ...
                        'Spatial ratio');
                    
                    return
                end
                
                
                % =========================================================
                % BUILD EXACT 2 x 2 MATRIX
                %
                % Row 1 = numerator
                % Row 2 = denominator
                % =========================================================
                
                numeratorBand = ...
                    sort(vals(1:2));
                
                denominatorBand = ...
                    sort(vals(3:4));
                
                
                manualBands = ...
                    [ ...
                    numeratorBand(:).'; ...
                    denominatorBand(:).' ...
                    ];
                
                
                % =========================================================
                % VALIDATE BAND WIDTH
                % =========================================================
                
                if manualBands(1,2) <= manualBands(1,1)
                    
                    uialert( ...
                        app.UIFigure, ...
                        'Numerator band must have different start/end values.', ...
                        'Spatial ratio');
                    
                    return
                end
                
                
                if manualBands(2,2) <= manualBands(2,1)
                    
                    uialert( ...
                        app.UIFigure, ...
                        'Denominator band must have different start/end values.', ...
                        'Spatial ratio');
                    
                    return
                end
                
                
                selectionMode = ...
                    "Manual bands";
                
                
                ok = ...
                    true;
                
                return
                
            end
            
        end
        
        
        function initializePreprocessWorkflowState(app)
            
            % =========================================================
            % 1. CREATE STANDARD EMPTY CHECKPOINTS
            % =========================================================
            cosmicCP = app.createEmptyPreprocessCheckpoint();
            cosmicCP.Stage = "Cosmic";
            
            baselineCP = app.createEmptyPreprocessCheckpoint();
            baselineCP.Stage = "Baseline";
            
            smoothCP = app.createEmptyPreprocessCheckpoint();
            smoothCP.Stage = "Smooth";
            
            % =========================================================
            % 2. INITIALIZE WORKFLOW CONTAINER
            % =========================================================
            app.preprocessCheckpoints = struct();
            
            app.preprocessCheckpoints.Cosmic = cosmicCP;
            app.preprocessCheckpoints.Baseline = baselineCP;
            app.preprocessCheckpoints.Smooth = smoothCP;
            
            % =========================================================
            % 3. CLEAR PENDING CONTEXT
            % =========================================================
            app.pendingPreprocessContext = struct();
            
        end


        function ensurePreprocessWorkflowState(app)
            
            requiredStages = [ ...
                "Cosmic", ...
                "Baseline", ...
                "Smooth"];
            
            % =========================================================
            % 1. INITIALIZE WHOLE WORKFLOW WHEN MISSING
            % =========================================================
            if isempty(app.preprocessCheckpoints) || ...
                    ~isstruct(app.preprocessCheckpoints)
                
                app.initializePreprocessWorkflowState();
                return
            end
            
            % =========================================================
            % 2. REPAIR EVERY STAGE
            %
            % This supports:
            %   - a new session;
            %   - an old saved session;
            %   - an empty struct left by a reset;
            %   - checkpoints created with an older schema.
            % =========================================================
            for iStage = 1:numel(requiredStages)
                
                stageName = requiredStages(iStage);
                stageField = char(stageName);
                
                defaultCP = app.createEmptyPreprocessCheckpoint();
                defaultCP.Stage = stageName;
                
                % -----------------------------------------------------
                % Stage does not exist
                % -----------------------------------------------------
                if ~isfield(app.preprocessCheckpoints, stageField)
                    
                    app.preprocessCheckpoints.(stageField) = defaultCP;
                    continue
                end
                
                currentCP = ...
                    app.preprocessCheckpoints.(stageField);
                
                % -----------------------------------------------------
                % Stage exists but is invalid or empty
                % -----------------------------------------------------
                if isempty(currentCP) || ...
                        ~isstruct(currentCP) || ...
                        numel(currentCP) ~= 1
                    
                    app.preprocessCheckpoints.(stageField) = defaultCP;
                    continue
                end
                
                % -----------------------------------------------------
                % Upgrade an old checkpoint by adding missing fields
                % -----------------------------------------------------
                requiredFields = ...
                    fieldnames(defaultCP);
                
                for iField = 1:numel(requiredFields)
                    
                    fieldName = requiredFields{iField};
                    
                    if ~isfield(currentCP, fieldName)
                        
                        currentCP.(fieldName) = ...
                            defaultCP.(fieldName);
                        
                    end
                end
                
                % -----------------------------------------------------
                % Normalize critical field types
                % -----------------------------------------------------
                try
                    currentCP.Completed = ...
                        logical(currentCP.Completed);
                catch
                    currentCP.Completed = false;
                end
                
                if isempty(currentCP.Completed)
                    currentCP.Completed = false;
                else
                    currentCP.Completed = currentCP.Completed(1);
                end
                
                try
                    currentCP.DataChanged = ...
                        logical(currentCP.DataChanged);
                catch
                    currentCP.DataChanged = false;
                end
                
                if isempty(currentCP.DataChanged)
                    currentCP.DataChanged = false;
                else
                    currentCP.DataChanged = currentCP.DataChanged(1);
                end
                
                currentCP.Stage = stageName;
                
                % -----------------------------------------------------
                % Store repaired checkpoint
                % -----------------------------------------------------
                app.preprocessCheckpoints.(stageField) = ...
                    currentCP;
                
            end
            
            % =========================================================
            % 3. ENSURE PENDING CONTEXT IS VALID
            % =========================================================
            if isempty(app.pendingPreprocessContext) || ...
                    ~isstruct(app.pendingPreprocessContext)
                
                app.pendingPreprocessContext = struct();
                
            end
            
        end
        
        
        function order = getPreprocessStageOrder(app, stageName)
            switch string(stageName)
                case "Cosmic"
                    order = 1;
                case "Baseline"
                    order = 2;
                case "Smooth"
                    order = 3;
                otherwise
                    order = Inf;
            end
        end
        
        
        function tf = hasAnyAnalysisResult(app)
            
            tf = false;
            
            
            % =========================================================
            % Mean spectra result
            % =========================================================
            if ~isempty(app.plottedMeanSpectra)
                
                tf = true;
                
                return
                
            end
            
            
            % =========================================================
            % Ratio
            % =========================================================
            if ~isempty(app.ratioResults) || ...
                    ~isempty(app.ratioScreeningAll) || ...
                    ~isempty(app.ratioScreeningValues)
                
                tf = true;
                
                return
                
            end
            
            
            % =========================================================
            % PCA
            % =========================================================
            if ~isempty(app.pcaScores) || ...
                    ~isempty(app.pcaLoadings)
                
                tf = true;
                
                return
                
            end
            
            
            % =========================================================
            % Classification
            % =========================================================
            if ~isempty(app.classScores) || ...
                    ~isempty(app.classPredictedLabels)
                
                tf = true;
                
                return
                
            end
            
            
            % =========================================================
            % Scan derived analysis
            % =========================================================
            if ~isempty(app.scanFeatures) || ...
                    ~isempty(app.scanCurrentFeatureMap)
                
                tf = true;
                
            end
            
        end


        function tf = shouldAskPreprocessInputMode(app, targetStage)
            
            app.ensurePreprocessWorkflowState();
            
            targetStage = string(targetStage);
            tf = false;
            
            validStages = [ ...
                "Cosmic", ...
                "Baseline", ...
                "Smooth"];
            
            % =========================================================
            % 1. VALIDATE TARGET STAGE
            % =========================================================
            if ~ismember(targetStage, validStages)
                
                error( ...
                    'single_mode:InvalidPreprocessStage', ...
                    'Unknown preprocessing stage: %s', ...
                    targetStage);
                
            end
            
            % =========================================================
            % 2. TARGET STAGE ITSELF WAS ALREADY COMPLETED
            % =========================================================
            cp = ...
                app.preprocessCheckpoints.(char(targetStage));
            
            targetCompleted = false;
            
            if isstruct(cp) && ...
                    isfield(cp, 'Completed') && ...
                    ~isempty(cp.Completed)
                
                targetCompleted = ...
                    logical(cp.Completed(1));
                
            end
            
            if targetCompleted
                
                tf = true;
                return
                
            end
            
            % =========================================================
            % 3. A LATER PREPROCESSING STAGE WAS COMPLETED
            % =========================================================
            targetOrder = ...
                app.getPreprocessStageOrder(targetStage);
            
            for iStage = 1:numel(validStages)
                
                thisStage = validStages(iStage);
                
                thisOrder = ...
                    app.getPreprocessStageOrder(thisStage);
                
                if thisOrder <= targetOrder
                    continue
                end
                
                thisCP = ...
                    app.preprocessCheckpoints.(char(thisStage));
                
                laterCompleted = false;
                
                if isstruct(thisCP) && ...
                        isfield(thisCP, 'Completed') && ...
                        ~isempty(thisCP.Completed)
                    
                    laterCompleted = ...
                        logical(thisCP.Completed(1));
                    
                end
                
                if laterCompleted
                    
                    tf = true;
                    return
                    
                end
                
            end
            
            % =========================================================
            % 4. ANALYSIS RESULTS ALREADY EXIST
            % =========================================================
            if app.hasAnyAnalysisResult()
                tf = true;
            end
            
        end
        
        
        function [inputSpectra, inputCube, inputHistory] = ...
                getRestartInputForStage(app, targetStage)
            
            app.ensurePreprocessWorkflowState();
            
            
            targetStage = ...
                string(targetStage);
            
            
            inputSpectra = [];
            
            inputCube = [];
            
            inputHistory = "";
            
            
            % =========================================================
            % 1. Best option:
            % use previous INPUT of the same stage
            % =========================================================
            cp = ...
                app.preprocessCheckpoints.(char(targetStage));
            
            checkpointCompleted = false;
            
            if isstruct(cp) && ...
                    isfield(cp, 'Completed') && ...
                    ~isempty(cp.Completed)
                
                checkpointCompleted = ...
                    logical(cp.Completed(1));
                
            end
            
            if checkpointCompleted
                
                if app.mode == 1 && ...
                        ~isempty(cp.InputSpectra)
                    
                    inputSpectra = ...
                        cp.InputSpectra;
                    
                    inputHistory = ...
                        string(cp.InputHistory);
                    
                    return
                    
                end
                
                
                if app.mode == 2 && ...
                        ~isempty(cp.InputCube)
                    
                    inputCube = ...
                        cp.InputCube;
                    
                    inputHistory = ...
                        string(cp.InputHistory);
                    
                    return
                    
                end
                
            end
            
            
            % =========================================================
            % 2. First-time fallback:
            % reconstruct canonical stage input
            % =========================================================
            switch targetStage
                
                
                % -----------------------------------------------------
                % COSMIC starts from original/raw data
                % -----------------------------------------------------
                case "Cosmic"
                    
                    if app.mode == 1
                        
                        if ~isempty(app.rawSpectra)
                            
                            inputSpectra = ...
                                app.rawSpectra;
                            
                        else
                            
                            inputSpectra = ...
                                app.spectra;
                            
                        end
                        
                    else
                        
                        if ~isempty(app.rawCube)
                            
                            inputCube = ...
                                app.rawCube;
                            
                        else
                            
                            inputCube = ...
                                app.cube;
                            
                        end
                        
                    end
                    
                    
                    inputHistory = ...
                        "Data checked";


                    % -----------------------------------------------------
                    % BASELINE normally starts after Cosmic
                    % -----------------------------------------------------
                case "Baseline"
                    
                    cosmicCP = ...
                        app.preprocessCheckpoints.Cosmic;
                    
                    
                    if cosmicCP.Completed
                        
                        if app.mode == 1 && ...
                                ~isempty(cosmicCP.OutputSpectra)
                            
                            inputSpectra = ...
                                cosmicCP.OutputSpectra;
                            
                            inputHistory = ...
                                string(cosmicCP.OutputHistory);
                            
                            return
                            
                        end
                        
                        
                        if app.mode == 2 && ...
                                ~isempty(cosmicCP.OutputCube)
                            
                            inputCube = ...
                                cosmicCP.OutputCube;
                            
                            inputHistory = ...
                                string(cosmicCP.OutputHistory);
                            
                            return
                            
                        end
                        
                    end
                    
                    
                    % Fallback to raw
                    if app.mode == 1
                        
                        if ~isempty(app.rawSpectra)
                            
                            inputSpectra = ...
                                app.rawSpectra;
                            
                        else
                            
                            inputSpectra = ...
                                app.spectra;
                            
                        end
                        
                    else
                        
                        if ~isempty(app.rawCube)
                            
                            inputCube = ...
                                app.rawCube;
                            
                        else
                            
                            inputCube = ...
                                app.cube;
                            
                        end
                        
                    end
                    
                    
                    inputHistory = ...
                        "Data checked";


                    % -----------------------------------------------------
                    % SMOOTH normally starts after Baseline,
                    % otherwise after Cosmic,
                    % otherwise raw.
                    % -----------------------------------------------------
                case "Smooth"
                    
                    baselineCP = ...
                        app.preprocessCheckpoints.Baseline;
                    
                    
                    if baselineCP.Completed
                        
                        if app.mode == 1 && ...
                                ~isempty(baselineCP.OutputSpectra)
                            
                            inputSpectra = ...
                                baselineCP.OutputSpectra;
                            
                            inputHistory = ...
                                string(baselineCP.OutputHistory);
                            
                            return
                            
                        end
                        
                        
                        if app.mode == 2 && ...
                                ~isempty(baselineCP.OutputCube)
                            
                            inputCube = ...
                                baselineCP.OutputCube;
                            
                            inputHistory = ...
                                string(baselineCP.OutputHistory);
                            
                            return
                            
                        end
                        
                    end
                    
                    
                    cosmicCP = ...
                        app.preprocessCheckpoints.Cosmic;
                    
                    
                    if cosmicCP.Completed
                        
                        if app.mode == 1 && ...
                                ~isempty(cosmicCP.OutputSpectra)
                            
                            inputSpectra = ...
                                cosmicCP.OutputSpectra;
                            
                            inputHistory = ...
                                string(cosmicCP.OutputHistory);
                            
                            return
                            
                        end
                        
                        
                        if app.mode == 2 && ...
                                ~isempty(cosmicCP.OutputCube)
                            
                            inputCube = ...
                                cosmicCP.OutputCube;
                            
                            inputHistory = ...
                                string(cosmicCP.OutputHistory);
                            
                            return
                            
                        end
                        
                    end
                    
                    
                    % Raw fallback
                    if app.mode == 1
                        
                        if ~isempty(app.rawSpectra)
                            
                            inputSpectra = ...
                                app.rawSpectra;
                            
                        else
                            
                            inputSpectra = ...
                                app.spectra;
                            
                        end
                        
                    else
                        
                        if ~isempty(app.rawCube)
                            
                            inputCube = ...
                                app.rawCube;
                            
                        else
                            
                            inputCube = ...
                                app.cube;
                            
                        end
                        
                    end
                    
                    
                    inputHistory = ...
                        "Data checked";


                otherwise
                    
                    error( ...
                        'Unknown preprocess stage: %s', ...
                        targetStage);
                    
            end
            
        end


        function [inputSpectra, inputCube, ok] = ...
                preparePreprocessStage(app, targetStage)
            
            app.ensurePreprocessWorkflowState();
            
            
            targetStage = ...
                string(targetStage);
            
            
            inputSpectra = [];
            
            inputCube = [];
            
            ok = false;
            
            
            % =========================================================
            % Validate current data
            % =========================================================
            if isempty(app.mode) || isempty(app.wn)
                
                uialert( ...
                    app.UIFigure, ...
                    'No current preprocessing data are available.', ...
                    'No data');
                
                return
                
            end
            
            
            % =========================================================
            % Default:
            % current working data
            % =========================================================
            actionMode = ...
                "Current";
            
            
            if app.mode == 1
                
                inputSpectra = ...
                    app.spectra;
                
            else
                
                inputCube = ...
                    app.cube;
                
            end
            
            
            inputHistory = ...
                app.processHistory;
            
            
            % =========================================================
            % Ask only when re-entry/backtracking is meaningful
            % =========================================================
            if app.shouldAskPreprocessInputMode(targetStage)
                
                pipelineText = ...
                    app.processHistory;
                
                
                if strlength(pipelineText) == 0
                    
                    pipelineText = ...
                        "Current processed data";
                    
                end
                
                
                msg = sprintf([ ...
                    'Re-run %s\n\n' ...
                    'Current pipeline:\n%s\n\n' ...
                    'Choose input mode:\n\n' ...
                    'PROCESS CURRENT DATA\n' ...
                    '- Run %s on the current working data.\n' ...
                    '- Existing preprocessing is preserved and the new step is stacked on top.\n\n' ...
                    'RESTART FROM STAGE INPUT\n' ...
                    '- Return to the recorded input of %s.\n' ...
                    '- Re-run the stage from that checkpoint.\n' ...
                    '- Downstream preprocessing and analysis results from the old branch become stale.'], ...
                    targetStage, ...
                    pipelineText, ...
                    targetStage, ...
                    targetStage);
                
                
                choice = questdlg( ...
                    msg, ...
                    char("Re-run " + targetStage), ...
                    'Process current data', ...
                    'Restart from stage input', ...
                    'Cancel', ...
                    'Cancel');
                
                
                switch choice
                    
                    
                    % =================================================
                    % STACK CURRENT DATA
                    % =================================================
                    case 'Process current data'
                        
                        actionMode = ...
                            "Current";
                        
                        
                        if app.mode == 1
                            
                            inputSpectra = ...
                                app.spectra;
                            
                            inputCube = [];
                            
                        else
                            
                            inputCube = ...
                                app.cube;
                            
                            inputSpectra = [];
                            
                        end
                        
                        
                        inputHistory = ...
                            app.processHistory;


                        % =================================================
                        % RESTART STAGE
                        % =================================================
                    case 'Restart from stage input'
                        
                        actionMode = ...
                            "Restart";
                        
                        
                        [ ...
                            inputSpectra, ...
                            inputCube, ...
                            inputHistory] = ...
                            app.getRestartInputForStage( ...
                            targetStage);


                    otherwise
                        
                        return
                        
                end
                
            end
            
            
            % =========================================================
            % Validate resolved data
            % =========================================================
            if app.mode == 1 && ...
                    isempty(inputSpectra)
                
                uialert( ...
                    app.UIFigure, ...
                    'Resolved single-spectra input is empty.', ...
                    'Preprocess input error');
                
                return
                
            end
            
            
            if app.mode == 2 && ...
                    isempty(inputCube)
                
                uialert( ...
                    app.UIFigure, ...
                    'Resolved scan-cube input is empty.', ...
                    'Preprocess input error');
                
                return
                
            end
            
            
            % =========================================================
            % Save pending context
            %
            % IMPORTANT:
            % We do NOT invalidate anything yet.
            % User may close/cancel child app.
            % =========================================================
            app.pendingPreprocessContext = struct( ...
                'Stage', targetStage, ...
                'ActionMode', actionMode, ...
                'InputSpectra', inputSpectra, ...
                'InputCube', inputCube, ...
                'InputHistory', inputHistory, ...
                'OpenedAt', string(datetime('now')));
            
            
            ok = true;
            
        end


        function invalidateDownstreamPreprocess(app, targetStage)
            
            app.ensurePreprocessWorkflowState();
            
            targetStage = string(targetStage);
            
            targetOrder = ...
                app.getPreprocessStageOrder(targetStage);
            
            stageNames = [ ...
                "Cosmic", ...
                "Baseline", ...
                "Smooth"];
            
            for iStage = 1:numel(stageNames)
                
                thisStage = stageNames(iStage);
                
                thisOrder = ...
                    app.getPreprocessStageOrder(thisStage);
                
                if thisOrder > targetOrder
                    
                    emptyCP = ...
                        app.createEmptyPreprocessCheckpoint();
                    
                    emptyCP.Stage = thisStage;
                    
                    app.preprocessCheckpoints.(char(thisStage)) = ...
                        emptyCP;
                    
                end
                
            end
            
        end

        function clearDerivedAnalysisState(app)
            
            % =========================================================
            % MEAN SPECTRA DISPLAY CACHE
            % =========================================================
            app.plottedMeanSpectra = [];
            
            app.plottedSDSpectra = [];
            
            app.plottedGroupNames = ...
                strings(0,1);
            
            app.plottedWn = [];
            
            
            % =========================================================
            % RATIO
            % =========================================================
            app.ratioFormula = "";
            
            app.ratioBands = table();
            
            app.ratioResults = table();
            
            app.boxplotResults = table();
            
            app.ratioDefinitions = table();
            
            
            app.ratioScreeningSelectedPeaks = ...
                table();
            
            app.ratioScreeningAll = ...
                table();
            
            app.ratioScreeningTop = ...
                table();
            
            app.ratioScreeningValues = [];
            
            app.ratioScreeningGroupLabels = ...
                strings(0,1);
            
            app.ratioScreeningGroupNames = ...
                strings(0,1);
            
            app.ratioScreeningSpectrumNames = ...
                strings(0,1);
            
            
            % =========================================================
            % PCA
            % =========================================================
            app.pcaInputType = ...
                "Spectra";
            
            app.pcaVariableNames = ...
                strings(0,1);
            
            app.pcaSourceX = [];
            
            app.pcaX = [];
            
            app.pcaWn = [];
            
            app.pcaScores = [];
            
            app.pcaLoadings = [];
            
            app.pcaExplained = [];
            
            app.pcaGroupLabels = ...
                strings(0,1);
            
            app.pcaGroupNames = ...
                strings(0,1);
            
            app.pcaSpectrumNames = ...
                strings(0,1);
            
            app.pcaNormMethod = "";
            
            
            app.pcaReferenceValues = [];
            
            app.pcaReferenceScaledX = [];
            
            app.pcaReferenceDefinition = ...
                struct();
            
            app.pcaReferenceFeatureType = "";
            
            
            app.currentPCX = 1;
            
            app.currentPCY = 2;
            
            app.currentLoadingPCs = [];
            
            
            % =========================================================
            % CLASSIFICATION
            % =========================================================
            app.classMethod = "";
            
            app.classX = [];
            
            app.classWn = [];
            
            app.classGroupLabels = ...
                strings(0,1);
            
            app.classGroupNames = ...
                strings(0,1);
            
            app.classSpectrumNames = ...
                strings(0,1);
            
            app.classNormMethod = "";
            
            
            app.classScores = [];
            
            app.classLoadings = [];
            
            app.classExplained = [];
            
            app.classComponentNames = ...
                strings(0,1);
            
            app.classPredictedLabels = ...
                strings(0,1);
            
            app.classAccuracy = NaN;
            
            app.classConfusion = table();
            
            
            app.currentClassScoreX = 1;
            
            app.currentClassScoreY = 2;
            
            app.currentClassLoadings = [];
            
            
            % =========================================================
            % SCAN DERIVED ANALYSIS
            % =========================================================
            app.scanLoaded = false;
            
            app.scanProcessedCube = [];
            
            app.scanCurrentAxis1Map = [];
            
            app.scanCurrentAxis2Map = [];
            
            app.scanCurrentAxis1Title = "";
            
            app.scanCurrentAxis2Title = "";
            
            app.scanCurrentFeatureMap = [];
            
            app.scanCurrentFeatureName = "";
            
            app.scanCurrentFeatureType = "";
            
            app.scanCurrentFeatureFormula = "";
            
            
            app.scanFeatures = struct( ...
                'Name', {}, ...
                'Type', {}, ...
                'Map', {}, ...
                'Formula', {}, ...
                'BandInfo', {}, ...
                'Source', {}, ...
                'CreatedAt', {});
            
            
            % =========================================================
            % CLEAR ANALYSIS AXES/TREES
            % =========================================================
            try
                cla(app.UIAxes4, 'reset');
                cla(app.UIAxes5, 'reset');
                cla(app.UIAxes4_2, 'reset');
                cla(app.UIAxes5_2, 'reset');
            catch
            end
            
            
            try
                delete(app.Tree.Children);
                delete(app.Tree2.Children);
                delete(app.Tree_2.Children);
                delete(app.Tree2_2.Children);
            catch
            end
            
        end
        
        
        function commitPreprocessStage( ...
                app, ...
                stageName, ...
                outputSpectra, ...
                outputCube, ...
                historyText, ...
                resultStatus, ...
                dataChanged, ...
                resultInfo)
            
            % =========================================================
            % 1. OPTIONAL RESULT INFORMATION
            % =========================================================
            if nargin < 9 || isempty(resultInfo)
                resultInfo = struct();
            end
            
            app.ensurePreprocessWorkflowState();
            
            stageName = string(stageName);
            historyText = string(historyText);
            resultStatus = string(resultStatus);
            dataChanged = logical(dataChanged);
            
            % =========================================================
            % 2. RESOLVE PENDING PREPROCESS CONTEXT
            % =========================================================
            ctxValid = ...
                ~isempty(app.pendingPreprocessContext) && ...
                isstruct(app.pendingPreprocessContext) && ...
                isfield(app.pendingPreprocessContext, 'Stage') && ...
                string(app.pendingPreprocessContext.Stage) == stageName;
            
            if ctxValid
                
                ctx = app.pendingPreprocessContext;
                
            else
                
                % Fallback protection when a child result is returned without
                % an active pending preprocess context.
                ctx = struct();
                
                ctx.Stage = stageName;
                ctx.ActionMode = "Current";
                ctx.InputSpectra = app.spectra;
                ctx.InputCube = app.cube;
                ctx.InputHistory = app.processHistory;
                
            end
            
            % =========================================================
            % 3. ENSURE REQUIRED CONTEXT FIELDS EXIST
            % =========================================================
            if ~isfield(ctx, 'ActionMode') || ...
                    strlength(string(ctx.ActionMode)) == 0
                ctx.ActionMode = "Current";
            end
            
            if ~isfield(ctx, 'InputSpectra')
                ctx.InputSpectra = [];
            end
            
            if ~isfield(ctx, 'InputCube')
                ctx.InputCube = [];
            end
            
            if ~isfield(ctx, 'InputHistory')
                ctx.InputHistory = app.processHistory;
            end
            
            % =========================================================
            % 4. RESTART INVALIDATES DOWNSTREAM STAGES
            % =========================================================
            if string(ctx.ActionMode) == "Restart"
                app.invalidateDownstreamPreprocess(stageName);
            end
            
            % =========================================================
            % 5. CHANGED PREPROCESSING INVALIDATES DERIVED ANALYSES
            % =========================================================
            if dataChanged
                app.clearDerivedAnalysisState();
            end
            
            % =========================================================
            % 6. MOVE CHILD OUTPUT INTO CURRENT WORKING DATA
            % =========================================================
            switch app.mode
                
                case 1
                    if isempty(outputSpectra)
                        error('commitPreprocessStage:EmptySpectra', ...
                            'No spectra were returned for stage %s.', stageName);
                    end
                    
                    if ~isempty(app.wn) && ...
                            size(outputSpectra, 1) ~= numel(app.wn)
                        error('commitPreprocessStage:DimensionMismatch', ...
                            ['Returned spectra for stage %s do not match ' ...
                            'the current wavenumber axis.'], stageName);
                    end
                    
                    app.spectra = outputSpectra;
                    app.cube = [];
                    
                case 2
                    if isempty(outputCube)
                        error('commitPreprocessStage:EmptyCube', ...
                            'No cube was returned for stage %s.', stageName);
                    end
                    
                    if ~isempty(app.wn) && ...
                            size(outputCube, 3) ~= numel(app.wn)
                        error('commitPreprocessStage:DimensionMismatch', ...
                            ['Returned cube for stage %s does not match ' ...
                            'the current wavenumber axis.'], stageName);
                    end
                    
                    app.cube = outputCube;
                    app.spectra = [];
                    
                otherwise
                    error('commitPreprocessStage:InvalidMode', ...
                        'Current data mode must be 1 or 2.');
            end
            
            % =========================================================
            % 7. RESTORE HISTORY OF SELECTED INPUT BRANCH
            % =========================================================
            app.processHistory = string(ctx.InputHistory);
            
            % =========================================================
            % 8. APPEND CURRENT STAGE
            % =========================================================
            app.appendHistory(historyText);
            
            % =========================================================
            % 9. BUILD CHECKPOINT
            % =========================================================
            cp = struct();
            
            cp.SchemaVersion = "1.0";
            cp.Stage = stageName;
            cp.Completed = true;
            
            cp.InputSpectra = ctx.InputSpectra;
            cp.InputCube = ctx.InputCube;
            
            cp.OutputSpectra = outputSpectra;
            cp.OutputCube = outputCube;
            
            cp.InputHistory = string(ctx.InputHistory);
            cp.OutputHistory = app.processHistory;
            
            cp.ActionMode = string(ctx.ActionMode);
            cp.AppliedAt = string(datetime( ...
                'now', ...
                'Format', 'yyyy-MM-dd HH:mm:ss'));
            
            cp.ResultStatus = resultStatus;
            cp.DataChanged = dataChanged;
            
            % Complete parameters, metrics and method returned by child app.
            cp.ResultInfo = resultInfo;
            
            % =========================================================
            % 10. SAVE CHECKPOINT IN WORKFLOW STRUCTURE
            % =========================================================
            app.preprocessCheckpoints.(char(stageName)) = cp;
            
            % =========================================================
            % 11. UPDATE WORKFLOW STATUS
            % =========================================================
            app.CurrentStage = stageName;
            app.CurrentDataStatus = resultStatus;
            
            % =========================================================
            % 12. CLEAR PENDING CONTEXT
            % =========================================================
            app.pendingPreprocessContext = struct();
            
            % =========================================================
            % 13. AUDIT MESSAGE
            % =========================================================
            methodText = "";
            
            if isstruct(resultInfo) && isfield(resultInfo, 'Method')
                methodText = string(resultInfo.Method);
            end
            
            auditDetails = ...
                "History: " + app.processHistory;
            
            if strlength(methodText) > 0
                auditDetails = ...
                    auditDetails + " | Method: " + methodText;
            end
            
            app.logActivity( ...
                "Preprocess", ...
                "Commit " + stageName, ...
                string(ctx.ActionMode), ...
                resultStatus, ...
                auditDetails);
        end


        function T = makeRatioReportTable(app, topN)
            
            if nargin < 2 || isempty(topN)
                topN = 20;
            end
            
            T = table();
            
            if isempty(app.ratioScreeningTop) || ...
                    ~istable(app.ratioScreeningTop)
                
                return
            end
            
            source = ...
                app.ratioScreeningTop;
            
            nRows = min( ...
                topN, ...
                height(source));
            
            source = ...
                source(1:nRows,:);
            
            vars = ...
                string(source.Properties.VariableNames);
            
            preferred = [ ...
                "Rank", ...
                "RatioDisplay", ...
                "NumeratorCenterWN", ...
                "DenominatorCenterWN", ...
                "Score", ...
                "PValue", ...
                "QValue", ...
                "EffectSize", ...
                "Direction"];
            
            preferred = ...
                preferred(ismember(preferred,vars));
            
            if isempty(preferred)
                
                preferred = [ ...
                    "Rank", ...
                    "RatioName", ...
                    "Score", ...
                    "PValue", ...
                    "QValue", ...
                    "EffectSize", ...
                    "Direction"];
                
                preferred = ...
                    preferred(ismember(preferred,vars));
                
            end
            
            T = ...
                source(:,cellstr(preferred));
            
            % Friendly names.
            oldNames = ...
                string(T.Properties.VariableNames);
            
            newNames = oldNames;
            
            newNames(oldNames == "RatioDisplay") = ...
                "Ratio";
            
            newNames(oldNames == "RatioName") = ...
                "Ratio";
            
            newNames(oldNames == "NumeratorCenterWN") = ...
                "NumWN";
            
            newNames(oldNames == "DenominatorCenterWN") = ...
                "DenWN";
            
            newNames(oldNames == "EffectSize") = ...
                "Effect";
            
            T.Properties.VariableNames = ...
                cellstr(newNames);
            
            % Round numeric values.
            numericCandidates = [ ...
                "NumWN", ...
                "DenWN", ...
                "Score", ...
                "Effect", ...
                "Direction"];
            
            for name = numericCandidates
                
                if ismember( ...
                        name, ...
                        string(T.Properties.VariableNames))
                    
                    T.(char(name)) = ...
                        round( ...
                        double(T.(char(name))), ...
                        3);
                    
                end
                
            end
            
            if ismember( ...
                    'PValue', ...
                    T.Properties.VariableNames)
                
                T.PValue = ...
                    string(compose( ...
                    '%.3e', ...
                    T.PValue));
                
            end
            
            if ismember( ...
                    'QValue', ...
                    T.Properties.VariableNames)
                
                T.QValue = ...
                    string(compose( ...
                    '%.3e', ...
                    T.QValue));
                
            end
            
        end


        function appendRatioReportTablePages( ...
                app, ...
                pdfPath, ...
                T, ...
                pageTitle, ...
                appendMode)
            
            if nargin < 6
                appendMode = true;
            end
            
            if isempty(T) || ...
                    ~istable(T)
                
                return
            end
            
            rowsPerPage = 12;
            
            nPages = ceil( ...
                height(T) / rowsPerPage);
            
            for p = 1:nPages
                
                firstRow = ...
                    (p-1) * rowsPerPage + 1;
                
                lastRow = min( ...
                    p * rowsPerPage, ...
                    height(T));
                
                pageTable = ...
                    T(firstRow:lastRow,:);
                
                % =====================================================
                % Convert all table contents to displayable cell data
                % =====================================================
                cellData = cell( ...
                    height(pageTable), ...
                    width(pageTable));
                
                for c = 1:width(pageTable)
                    
                    values = ...
                        pageTable{:,c};
                    
                    if isnumeric(values)
                        
                        for r = 1:size(values,1)
                            
                            value = values(r);
                            
                            if isfinite(value)
                                
                                cellData{r,c} = ...
                                    sprintf('%.4g',value);
                                
                            else
                                
                                cellData{r,c} = ...
                                    '';
                                
                            end
                            
                        end
                        
                    elseif islogical(values)
                        
                        for r = 1:numel(values)
                            cellData{r,c} = char(string(values(r)));
                        end
                        
                    else
                        
                        values = string(values);
                        
                        for r = 1:numel(values)
                            
                            if ismissing(values(r))
                                cellData{r,c} = '';
                            else
                                cellData{r,c} = char(values(r));
                            end
                            
                        end
                        
                    end
                    
                end
                
                % =====================================================
                % Landscape A4 figure
                % =====================================================
                f = figure( ...
                    'Visible', ...
                    'off', ...
                    'Color', ...
                    'w', ...
                    'Units', ...
                    'centimeters', ...
                    'Position', ...
                    [2 2 29.7 21], ...
                    'PaperOrientation', ...
                    'landscape');
                
                cleanupObj = ...
                    onCleanup(@() delete(f));
                
                annotation( ...
                    f, ...
                    'textbox', ...
                    [0.04 0.90 0.92 0.07], ...
                    'String', ...
                    sprintf( ...
                    '%s — Page %d / %d', ...
                    pageTitle, ...
                    p, ...
                    nPages), ...
                    'EdgeColor', ...
                    'none', ...
                    'FontName', ...
                    'Arial', ...
                    'FontSize', ...
                    16, ...
                    'FontWeight', ...
                    'bold', ...
                    'Interpreter', ...
                    'none');
                
                uit = uitable( ...
                    f, ...
                    'Data', ...
                    cellData, ...
                    'ColumnName', ...
                    pageTable.Properties.VariableNames, ...
                    'RowName', ...
                    [], ...
                    'Units', ...
                    'normalized', ...
                    'Position', ...
                    [0.035 0.08 0.93 0.79], ...
                    'FontName', ...
                    'Arial', ...
                    'FontSize', ...
                    8);
                
                % =====================================================
                % Adaptive column widths
                % =====================================================
                columnNames = ...
                    string(pageTable.Properties.VariableNames);
                
                widths = ...
                    repmat({70},1,width(pageTable));
                
                for c = 1:numel(columnNames)
                    
                    switch columnNames(c)
                        
                        case "Rank"
                            widths{c} = 42;
                            
                        case "Ratio"
                            widths{c} = 310;
                            
                        case {"NumWN","DenWN"}
                            widths{c} = 75;
                            
                        case {"PValue","QValue"}
                            widths{c} = 85;
                            
                        case {"Score","Effect","Direction"}
                            widths{c} = 70;
                            
                        otherwise
                            widths{c} = 90;
                            
                    end
                    
                end
                
                uit.ColumnWidth = ...
                    widths;
                
                drawnow;
                
                if appendMode || p > 1 || isfile(pdfPath)
                    
                    exportgraphics( ...
                        f, ...
                        pdfPath, ...
                        'ContentType', ...
                        'vector', ...
                        'BackgroundColor', ...
                        'white', ...
                        'Append', ...
                        true);
                    
                else
                    
                    exportgraphics( ...
                        f, ...
                        pdfPath, ...
                        'ContentType', ...
                        'vector', ...
                        'BackgroundColor', ...
                        'white');
                    
                end
                
            end
            
        end


        function available = getReportSectionAvailability(app)
            
            % =========================================================
            % REPORT SECTION AVAILABILITY
            %
            % Returns one logical value for every supported section.
            % =========================================================
            
            available = struct();
            
            available.COVER = true;
            
            available.QC_SUMMARY = ...
                ~isempty(app.singleQC) && ...
                isstruct(app.singleQC) && ...
                isfield(app.singleQC, 'Status');
            
            available.QC_ISSUES = ...
                available.QC_SUMMARY;
            
            available.PREPROCESS_WORKFLOW = ...
                strlength(string(app.processHistory)) > 0;
            
            available.PREPROCESS_HISTORY = ...
                ~isempty(app.ActivityHistory);
            
            available.MEAN_SPECTRA = ...
                ~isempty(app.plottedMeanSpectra) && ...
                ~isempty(app.plottedWn);
            
            available.RATIO_RESULTS = ...
                ~isempty(app.ratioScreeningTop) && ...
                istable(app.ratioScreeningTop);
            
            available.PCA_SCORE = ...
                ~isempty(app.pcaScores);
            
            available.PCA_LOADING = ...
                ~isempty(app.pcaLoadings);
            
            available.CLASS_SUMMARY = ...
                strlength(app.classMethod) > 0;
            
            available.CLASS_SCORE = ...
                ~isempty(app.classScores);
            
            available.CLASS_LOADING = ...
                ~isempty(app.classLoadings);
            
            available.FULL_ACTIVITY = ...
                ~isempty(app.ActivityHistory);
            
        end


        function T = buildAvailableReportSectionTable(app)
            
            availability = ...
                app.getReportSectionAvailability();
            
            Key = [ ...
                "COVER"; ...
                "QC_SUMMARY"; ...
                "QC_ISSUES"; ...
                "PREPROCESS_WORKFLOW"; ...
                "PREPROCESS_HISTORY"; ...
                "MEAN_SPECTRA"; ...
                "RATIO_RESULTS"; ...
                "PCA_SCORE"; ...
                "PCA_LOADING"; ...
                "CLASS_SUMMARY"; ...
                "CLASS_SCORE"; ...
                "CLASS_LOADING"; ...
                "FULL_ACTIVITY"];
            
            Category = [ ...
                "General"; ...
                "Quality control"; ...
                "Quality control"; ...
                "Preprocessing"; ...
                "Preprocessing"; ...
                "Analysis"; ...
                "Analysis"; ...
                "Analysis"; ...
                "Analysis"; ...
                "Analysis"; ...
                "Analysis"; ...
                "Analysis"; ...
                "Audit"];
            
            Label = [ ...
                "Cover and data overview"; ...
                "Quality control summary"; ...
                "QC issues and decisions"; ...
                "Processing workflow"; ...
                "Processing activity history"; ...
                "Mean spectra"; ...
                "Ratio screening results"; ...
                "PCA score plot"; ...
                "PCA loading plot"; ...
                "Classification summary"; ...
                "Classification score plot"; ...
                "Classification loading / importance"; ...
                "Full activity history"];
            
            Type = [ ...
                "text"; ...
                "text"; ...
                "text"; ...
                "text"; ...
                "table"; ...
                "figure"; ...
                "table"; ...
                "figure"; ...
                "figure"; ...
                "text"; ...
                "figure"; ...
                "figure"; ...
                "table"];
            
            DefaultLayout = [ ...
                "Portrait"; ...
                "Portrait"; ...
                "Portrait"; ...
                "Portrait"; ...
                "Landscape"; ...
                "Full page figure"; ...
                "Landscape"; ...
                "Full page figure"; ...
                "Full page figure"; ...
                "Portrait"; ...
                "Full page figure"; ...
                "Full page figure"; ...
                "Landscape"];
            
            DefaultTopN = [ ...
                NaN; ...
                NaN; ...
                NaN; ...
                NaN; ...
                NaN; ...
                NaN; ...
                20; ...
                NaN; ...
                NaN; ...
                NaN; ...
                NaN; ...
                NaN; ...
                NaN];
            
            DefaultRowsPerPage = [ ...
                NaN; ...
                NaN; ...
                NaN; ...
                NaN; ...
                18; ...
                NaN; ...
                12; ...
                NaN; ...
                NaN; ...
                NaN; ...
                NaN; ...
                NaN; ...
                18];
            
            Available = false(numel(Key),1);
            
            for i = 1:numel(Key)
                
                keyField = char(Key(i));
                
                if isfield(availability, keyField)
                    Available(i) = ...
                        logical(availability.(keyField));
                end
                
            end
            
            T = table( ...
                Key, ...
                Category, ...
                Label, ...
                Type, ...
                DefaultLayout, ...
                DefaultTopN, ...
                DefaultRowsPerPage, ...
                Available);
            
        end


        function populateReportAvailableTree(app)
            
            % =========================================================
            % 1. REMOVE DESIGN-TIME PLACEHOLDER NODES
            % =========================================================
            delete(app.Tree4.Children);
            
            % =========================================================
            % 2. BUILD CURRENT AVAILABILITY TABLE
            % =========================================================
            app.ReportAvailableSections = ...
                app.buildAvailableReportSectionTable();
            
            T = app.ReportAvailableSections;
            
            % =========================================================
            % 3. ROOT
            % =========================================================
            root = uitreenode(app.Tree4);
            
            root.Text = ...
                'Available report sections';
            
            root.NodeData = struct( ...
                'Type', ...
                "root");
            
            % =========================================================
            % 4. CATEGORY NODES
            % =========================================================
            categories = unique( ...
                T.Category, ...
                'stable');
            
            for iCategory = 1:numel(categories)
                
                categoryName = ...
                    categories(iCategory);
                
                categoryNode = ...
                    uitreenode(root);
                
                categoryNode.Text = ...
                    char(categoryName);
                
                categoryNode.NodeData = struct( ...
                    'Type', ...
                    "category", ...
                    'Category', ...
                    categoryName);
                
                sectionRows = find( ...
                    T.Category == categoryName);
                
                for iRow = sectionRows(:)'
                    
                    sectionNode = ...
                        uitreenode(categoryNode);
                    
                    if T.Available(iRow)
                        
                        sectionNode.Text = ...
                            char(T.Label(iRow));
                        
                    else
                        
                        sectionNode.Text = ...
                            char(T.Label(iRow) + ...
                            " [not available]");
                        
                    end
                    
                    sectionNode.NodeData = struct( ...
                        'Type', ...
                        "report_section", ...
                        'Key', ...
                        T.Key(iRow), ...
                        'Label', ...
                        T.Label(iRow), ...
                        'Available', ...
                        T.Available(iRow));
                    
                end
                
                expand(categoryNode);
                
            end
            
            expand(root);
            
        end


        function resetReportTemplate(app)
            
            % =========================================================
            % 1. REFRESH AVAILABLE SECTION INFORMATION
            % =========================================================
            app.ReportAvailableSections = ...
                app.buildAvailableReportSectionTable();
            
            availableT = ...
                app.ReportAvailableSections;
            
            % =========================================================
            % 2. DEFAULT TEMPLATE ORDER
            % =========================================================
            defaultKeys = [ ...
                "COVER"; ...
                "QC_SUMMARY"; ...
                "QC_ISSUES"; ...
                "PREPROCESS_WORKFLOW"; ...
                "PREPROCESS_HISTORY"; ...
                "MEAN_SPECTRA"; ...
                "RATIO_RESULTS"; ...
                "PCA_SCORE"; ...
                "PCA_LOADING"; ...
                "CLASS_SUMMARY"; ...
                "CLASS_SCORE"; ...
                "CLASS_LOADING"; ...
                "FULL_ACTIVITY"];
            
            n = numel(defaultKeys);
            
            Order = (1:n).';
            Include = false(n,1);
            Key = defaultKeys;
            Section = strings(n,1);
            NewPage = true(n,1);
            Layout = strings(n,1);
            TopN = nan(n,1);
            RowsPerPage = nan(n,1);
            IncludeDescription = true(n,1);
            CustomTitle = strings(n,1);
            
            for i = 1:n
                
                sourceRow = find( ...
                    availableT.Key == Key(i), ...
                    1, ...
                    'first');
                
                if isempty(sourceRow)
                    continue
                end
                
                Section(i) = ...
                    availableT.Label(sourceRow);
                
                Layout(i) = ...
                    availableT.DefaultLayout(sourceRow);
                
                TopN(i) = ...
                    availableT.DefaultTopN(sourceRow);
                
                RowsPerPage(i) = ...
                    availableT.DefaultRowsPerPage(sourceRow);
                
                Include(i) = ...
                    availableT.Available(sourceRow);
                
            end
            
            % Cover is always included.
            Include(Key == "COVER") = true;
            
            app.ReportSpec = table( ...
                Order, ...
                Include, ...
                Key, ...
                Section, ...
                NewPage, ...
                Layout, ...
                TopN, ...
                RowsPerPage, ...
                IncludeDescription, ...
                CustomTitle);
            
            app.SelectedReportRow = [];
            
            app.refreshReportStructureTable();
            app.clearReportSectionSettings();
            
        end


        function refreshReportStructureTable(app)
            
            if isempty(app.ReportSpec)
                
                app.UITable.Data = table();
                return
                
            end
            
            % Ensure sequential order after add/remove/move.
            app.ReportSpec.Order = ...
                (1:height(app.ReportSpec)).';
            
            displayT = ...
                app.ReportSpec(:, { ...
                'Order', ...
                'Include', ...
                'Section', ...
                'NewPage', ...
                'Layout'});
            
            app.UITable.Data = ...
                displayT;
            
            app.UITable.ColumnEditable = ...
                [false true false true true];
            
            app.UITable.ColumnWidth = { ...
                55, ...
                65, ...
                '1x', ...
                75, ...
                110};
            
        end
        
        
        function clearReportSectionSettings(app)
            
            app.ReportCustomTitleEditField.Value = '';
            app.ReportTopEditField.Value = '';
            app.ReportRowsPageEditField.Value = '';
            
            app.ReportLayoutDropDown.Items = { ...
                'Portrait', ...
                'Landscape', ...
                'Full page figure'};
            
            app.ReportLayoutDropDown.Value = ...
                'Portrait';
            
            app.CheckBox.Value = false;
            
            app.ReportCustomTitleEditField.Enable = 'off';
            app.ReportTopEditField.Enable = 'off';
            app.ReportRowsPageEditField.Enable = 'off';
            app.ReportLayoutDropDown.Enable = 'off';
            app.CheckBox.Enable = 'off';
            
        end
        
        
        function showReportSectionSettings(app, rowIndex)
            
            if isempty(app.ReportSpec) || ...
                    isempty(rowIndex) || ...
                    rowIndex < 1 || ...
                    rowIndex > height(app.ReportSpec)
                
                app.SelectedReportRow = [];
                app.clearReportSectionSettings();
                return
                
            end
            
            app.SelectedReportRow = ...
                rowIndex;
            
            row = ...
                app.ReportSpec(rowIndex,:);
            
            % =========================================================
            % Enable controls
            % =========================================================
            app.ReportCustomTitleEditField.Enable = 'on';
            app.ReportLayoutDropDown.Enable = 'on';
            app.CheckBox.Enable = 'on';
            
            % =========================================================
            % Custom title
            % =========================================================
            app.ReportCustomTitleEditField.Value = ...
                char(row.CustomTitle);
            
            % =========================================================
            % Layout
            % =========================================================
            allowedLayouts = string( ...
                app.ReportLayoutDropDown.Items);
            
            if any(allowedLayouts == row.Layout)
                
                app.ReportLayoutDropDown.Value = ...
                    char(row.Layout);
                
            else
                
                app.ReportLayoutDropDown.Value = ...
                    'Portrait';
                
            end
            
            % =========================================================
            % Description
            % =========================================================
            app.CheckBox.Value = ...
                logical(row.IncludeDescription);
            
            % =========================================================
            % Top N
            % =========================================================
            if isfinite(row.TopN)
                
                app.ReportTopEditField.Value = ...
                    num2str(row.TopN);
                
                app.ReportTopEditField.Enable = 'on';
                
            else
                
                app.ReportTopEditField.Value = '';
                app.ReportTopEditField.Enable = 'off';
                
            end
            
            % =========================================================
            % Rows per page
            % =========================================================
            if isfinite(row.RowsPerPage)
                
                app.ReportRowsPageEditField.Value = ...
                    num2str(row.RowsPerPage);
                
                app.ReportRowsPageEditField.Enable = 'on';
                
            else
                
                app.ReportRowsPageEditField.Value = '';
                app.ReportRowsPageEditField.Enable = 'off';
                
            end
            
        end
        
        
        function initializeReportBuilder(app)
            
            app.populateReportAvailableTree();
            
            if isempty(app.ReportSpec) || ...
                    ~istable(app.ReportSpec)
                
                app.resetReportTemplate();
                
            else
                
                app.refreshReportStructureTable();
                app.clearReportSectionSettings();
                
            end
            
            app.ReportBuilderInitialized = true;
            
        end


        function [sectionInfo, ok] = getSelectedAvailableReportSection(app)
            
            sectionInfo = struct();
            ok = false;
            
            nodes = app.Tree4.SelectedNodes;
            
            if isempty(nodes)
                return
            end
            
            nodeData = ...
                nodes(end).NodeData;
            
            if isempty(nodeData) || ...
                    ~isstruct(nodeData) || ...
                    ~isfield(nodeData, 'Type') || ...
                    string(nodeData.Type) ~= "report_section"
                
                return
            end
            
            if ~isfield(nodeData, 'Available') || ...
                    ~logical(nodeData.Available)
                
                uialert( ...
                    app.UIFigure, ...
                    ['This report section is not available because ' ...
                    'the corresponding analysis has not been completed.'], ...
                    'Section unavailable');
                
                return
            end
            
            sectionInfo = nodeData;
            ok = true;
            
        end
        
        
        function initializeSpatialDistribution(app)
            
            % =========================================================
            % INITIALIZE SPATIAL DISTRIBUTION WORKSPACE
            % =========================================================
            
            % ---------------------------------------------------------
            % 1. GLOBAL CONTROLS
            % ---------------------------------------------------------
            app.DisplaymaptypeDropDown.Items = { ...
                'Peak intensity', ...
                'Integrated band', ...
                'Baseline-corrected band area', ...
                'Normalized band', ...
                'Ratio / composite score', ...
                'Band ratio', ...
                'Area ratio', ...
                'Log ratio', ...
                'Peak position', ...
                'FWHM', ...
                'Pearson similarity', ...
                'Cosine similarity', ...
                'Spectral angle', ...
                'PCA score', ...
                'Cluster / class map'};
            
            app.DisplaymaptypeDropDown.Value = ...
                'Integrated band';
            
            
            app.MapsourceDropDown.Items = { ...
                'Processed', ...
                'Raw'};
            
            app.MapsourceDropDown.Value = ...
                'Processed';
            
            
            app.ColormapDropDown.Items = { ...
                'hot', ...
                'turbo', ...
                'parula', ...
                'jet', ...
                'bone', ...
                'gray'};
            
            app.ColormapDropDown.Value = ...
                'hot';
            
            
            app.ColorscaleDropDown.Items = { ...
                'Shared', ...
                'Individual'};
            
            app.ColorscaleDropDown.Value = ...
                'Shared';
            
            
            % ---------------------------------------------------------
            % 2. INITIALIZE PANEL STATES
            % ---------------------------------------------------------
            app.spatialPanels = repmat( ...
                struct( ...
                'PanelID', 0, ...
                'DatasetID', "", ...
                'DatasetName', "", ...
                'Recipe', struct(), ...
                'MapData', [], ...
                'ReferenceData', [], ...
                'MapTitle', "", ...
                'MapUnit', "", ...
                'Calculated', false, ...
                'CalculatedAt', "", ...
                'CLim', [NaN NaN]), ...
                6, ...
                1);
            
            for panelNumber = 1:6
                
                app.spatialPanels(panelNumber).PanelID = ...
                    panelNumber;
                
            end
            
            
            % ---------------------------------------------------------
            % 3. CLEAR TEMPORARY CACHE
            % ---------------------------------------------------------
            app.spatialCache = struct( ...
                'DatasetID', "", ...
                'RawCube', [], ...
                'ProcessedCube', [], ...
                'Wn', [], ...
                'LoadedAt', "");
            
            
            % ---------------------------------------------------------
            % 4. PREPARE AXES
            % ---------------------------------------------------------
            axesList = app.getSpatialAxes();
            
            for k = 1:numel(axesList)
                
                ax = axesList{k};
                
                cla(ax, 'reset');
                
                ax.Box = 'on';
                ax.XTick = [];
                ax.YTick = [];
                
                title( ...
                    ax, ...
                    sprintf('Panel %d — select dataset', k), ...
                    'Interpreter', ...
                    'none');
                
            end
            
            
            % ---------------------------------------------------------
            % 5. POPULATE DATASETS
            % ---------------------------------------------------------
            app.refreshSpatialDatasetDropdowns();
            
            
            % ---------------------------------------------------------
            % 6. DEFAULT RECIPE
            % ---------------------------------------------------------
            app.spatialCurrentRecipe = struct( ...
                'MapType', "Integrated band", ...
                'MapSource', "Processed", ...
                'Band1', [2800 2920], ...
                'Band2', [NaN NaN], ...
                'Normalization', "None", ...
                'LocalBaseline', "None", ...
                'MaskMode', "None", ...
                'Smoothing', "None", ...
                'CreatedAt', string(datetime('now')));
            
            
            app.spatialInitialized = true;
            
        end
        
        
        function axesList = getSpatialAxes(app)
            
            axesList = { ...
                app.UIAxes9, ...
                app.UIAxes9_2, ...
                app.UIAxes9_3, ...
                app.UIAxes9_4, ...
                app.UIAxes9_5, ...
                app.UIAxes9_6};
            
        end
        
        
        function dropdownList = getSpatialDatasetDropdowns(app)
            
            dropdownList = { ...
                app.DatasetDropDown, ...
                app.DatasetDropDown_2, ...
                app.DatasetDropDown_3, ...
                app.DatasetDropDown_4, ...
                app.DatasetDropDown_5, ...
                app.DatasetDropDown_6};
            
        end
        
        
        function rebuildSpatialDatasetRegistry(app)
            
            % =========================================================
            % BUILD SPATIAL DATASET REGISTRY
            %
            % Registration rule:
            %   spatial_source.mat exists
            %       -> register scan in dropdown
            %
            % Heavy Raman cube validation/loading is deferred until
            % the user actually selects or plots that dataset.
            %
            % This keeps the registry lightweight and prevents one
            % cube-loading problem from hiding a valid scan name.
            % =========================================================
            
            
            registry = struct( ...
                'ID', {}, ...
                'DisplayName', {}, ...
                'GroupName', {}, ...
                'SourceScanName', {}, ...
                'RawCubePath', {}, ...
                'ProcessedCubePath', {}, ...
                'WavenumberPath', {}, ...
                'MetadataPath', {}, ...
                'SourceSessionFolder', {}, ...
                'SourceStage', {}, ...
                'Ny', {}, ...
                'Nx', {}, ...
                'NWn', {}, ...
                'WnMin', {}, ...
                'WnMax', {}, ...
                'CreatedAt', {});
            
            
            % =========================================================
            % 1. REQUIRE LOADED GROUP TABLE
            % =========================================================
            
            if isempty(app.singleGroupTable)
                
                app.spatialDatasets = ...
                    registry;
                
                app.refreshSpatialDatasetDropdowns();
                
                return
            end
            
            
            % =========================================================
            % 2. LOOP THROUGH GROUPS ACCESSED IN REVIEW DATA
            % =========================================================
            
            for g = 1:height(app.singleGroupTable)
                
                groupName = ...
                    string( ...
                    app.singleGroupTable.DisplayName(g));
                
                inputFolder = ...
                    string( ...
                    app.singleGroupTable.InputFolder(g));
                
                
                if strlength(inputFolder) == 0 || ...
                        ~isfolder(inputFolder)
                    
                    continue
                end
                
                
                % =====================================================
                % 3. OFFICIAL SPATIAL SIDECAR
                % =====================================================
                
                spatialSourcePath = ...
                    fullfile( ...
                    inputFolder, ...
                    'spatial_source.mat');
                
                
                if ~isfile(spatialSourcePath)
                    
                    % This group has no spatial provenance.
                    % It remains usable as a normal single-spectrum group,
                    % but is not listed in Spatial distribution.
                    continue
                end
                
                
                % =====================================================
                % 4. READ LIGHTWEIGHT SIDECAR ONLY
                % =====================================================
                
                try
                    
                    T = ...
                        load( ...
                        spatialSourcePath, ...
                        'SpatialSource');
                    
                catch ME
                    
                    app.logActivity( ...
                        "Spatial distribution", ...
                        "Read spatial_source.mat", ...
                        groupName, ...
                        "WARNING", ...
                        string(ME.message));
                    
                    continue
                end
                
                
                if ~isfield(T, 'SpatialSource') || ...
                        ~isstruct(T.SpatialSource)
                    
                    app.logActivity( ...
                        "Spatial distribution", ...
                        "Read spatial_source.mat", ...
                        groupName, ...
                        "WARNING", ...
                        "SpatialSource structure is missing.");
                    
                    continue
                end
                
                
                S = ...
                    T.SpatialSource;
                
                
                % =====================================================
                % 5. SCAN IDENTITY
                % =====================================================
                
                scanName = "";
                
                if isfield(S, 'ScanName') && ...
                        ~isempty(S.ScanName)
                    
                    scanName = ...
                        string(S.ScanName);
                    
                elseif isfield(S, 'ScanID') && ...
                        ~isempty(S.ScanID)
                    
                    scanName = ...
                        string(S.ScanID);
                    
                end
                
                
                if strlength(scanName) == 0
                    
                    scanName = ...
                        groupName;
                end
                
                
                % =====================================================
                % 6. SPATIAL CUBE PATH
                %
                % Do NOT load the cube here.
                % Only retain provenance/path.
                % =====================================================
                
                processedCubePath = "";
                
                if isfield(S, 'ProcessedCubePath') && ...
                        ~isempty(S.ProcessedCubePath)
                    
                    processedCubePath = ...
                        string(S.ProcessedCubePath);
                    
                elseif isfield(S, 'ReadableMATPath') && ...
                        ~isempty(S.ReadableMATPath)
                    
                    processedCubePath = ...
                        string(S.ReadableMATPath);
                    
                elseif isfield(S, 'OriginalSourceMatPath') && ...
                        ~isempty(S.OriginalSourceMatPath)
                    
                    processedCubePath = ...
                        string(S.OriginalSourceMatPath);
                    
                elseif isfield(S, 'SourceMatPath') && ...
                        ~isempty(S.SourceMatPath)
                    
                    processedCubePath = ...
                        string(S.SourceMatPath);
                    
                end
                
                
                % =====================================================
                % 7. OPTIONAL RAW PATH
                % =====================================================
                
                rawCubePath = "";
                
                if isfield(S, 'RawCubePath') && ...
                        ~isempty(S.RawCubePath)
                    
                    rawCubePath = ...
                        string(S.RawCubePath);
                end
                
                
                % =====================================================
                % 8. SOURCE SESSION / EXTRACTION LOCATION
                % =====================================================
                
                sourceSessionFolder = "";
                
                if isfield(S, 'ExtractionFolder') && ...
                        ~isempty(S.ExtractionFolder)
                    
                    sourceSessionFolder = ...
                        string(S.ExtractionFolder);
                    
                elseif isfield(S, 'ClusterRunFolder') && ...
                        ~isempty(S.ClusterRunFolder)
                    
                    sourceSessionFolder = ...
                        string(S.ClusterRunFolder);
                    
                end
                
                
                % =====================================================
                % 9. DIMENSION METADATA
                %
                % Use sidecar metadata directly.
                % No 3-D cube load required.
                % =====================================================
                
                ny = NaN;
                nx = NaN;
                nWn = NaN;
                wnMin = NaN;
                wnMax = NaN;
                
                
                if isfield(S, 'Ny') && ...
                        ~isempty(S.Ny)
                    
                    ny = ...
                        double(S.Ny);
                end
                
                
                if isfield(S, 'Nx') && ...
                        ~isempty(S.Nx)
                    
                    nx = ...
                        double(S.Nx);
                end
                
                
                if isfield(S, 'NWn') && ...
                        ~isempty(S.NWn)
                    
                    nWn = ...
                        double(S.NWn);
                end
                
                
                if isfield(S, 'WnMin') && ...
                        ~isempty(S.WnMin)
                    
                    wnMin = ...
                        double(S.WnMin);
                end
                
                
                if isfield(S, 'WnMax') && ...
                        ~isempty(S.WnMax)
                    
                    wnMax = ...
                        double(S.WnMax);
                end
                
                
                % =====================================================
                % 10. ADD REGISTRY ENTRY
                % =====================================================
                
                newIndex = ...
                    numel(registry) + 1;
                
                
                registry(newIndex).ID = ...
                    "SPATIAL_" + ...
                    compose("%04d", newIndex);
                
                
                registry(newIndex).DisplayName = ...
                    scanName;
                
                
                registry(newIndex).GroupName = ...
                    groupName;
                
                
                registry(newIndex).SourceScanName = ...
                    scanName;
                
                
                registry(newIndex).RawCubePath = ...
                    rawCubePath;
                
                
                registry(newIndex).ProcessedCubePath = ...
                    processedCubePath;
                
                
                registry(newIndex).WavenumberPath = ...
                    processedCubePath;
                
                
                registry(newIndex).MetadataPath = ...
                    string(spatialSourcePath);
                
                
                registry(newIndex).SourceSessionFolder = ...
                    sourceSessionFolder;
                
                
                registry(newIndex).SourceStage = ...
                    "single_extract";
                
                
                registry(newIndex).Ny = ...
                    ny;
                
                
                registry(newIndex).Nx = ...
                    nx;
                
                
                registry(newIndex).NWn = ...
                    nWn;
                
                
                registry(newIndex).WnMin = ...
                    wnMin;
                
                
                registry(newIndex).WnMax = ...
                    wnMax;
                
                
                registry(newIndex).CreatedAt = ...
                    string(datetime('now'));
                
            end
            
            
            % =========================================================
            % 11. REMOVE DUPLICATES
            % =========================================================
            
            if ~isempty(registry)
                
                keys = ...
                    strings( ...
                    numel(registry), ...
                    1);
                
                
                for k = 1:numel(registry)
                    
                    keys(k) = ...
                        registry(k).SourceScanName + ...
                        "|" + ...
                        registry(k).ProcessedCubePath;
                    
                end
                
                
                [~, uniqueIdx] = ...
                    unique( ...
                    keys, ...
                    'stable');
                
                
                registry = ...
                    registry(uniqueIdx);
                
            end
            
            
            % =========================================================
            % 12. COMMIT REGISTRY
            % =========================================================
            
            app.spatialDatasets = ...
                registry;
            
            
            % =========================================================
            % 13. POPULATE ALL SIX DROPDOWNS
            % =========================================================
            
            app.refreshSpatialDatasetDropdowns();
            
            
            drawnow;
            
        end
        
        
        function tf = isSpatialCubeMatFile(app, matPath) %#ok<INUSD>
            
            % =============================================================
            % CHECK WHETHER MAT FILE CONTAINS A SPATIALLY RESOLVED RAMAN CUBE
            %
            % Supports:
            %
            % 1. Top-level:
            %       Cube
            %       RamanCube
            %       cube
            %
            % 2. READABLE format:
            %       RamanData.Cube
            %       RamanData.Wavenumber
            %
            % This function validates structure only.
            % =============================================================
            
            tf = false;
            
            matPath = ...
                char(string(matPath));
            
            if isempty(matPath) || ...
                    ~isfile(matPath)
                
                return
            end
            
            
            % =============================================================
            % 1. INSPECT TOP-LEVEL VARIABLES
            % =============================================================
            
            try
                
                vars = ...
                    whos( ...
                    '-file', ...
                    matPath);
                
            catch
                
                return
                
            end
            
            
            if isempty(vars)
                return
            end
            
            
            varNames = ...
                string({vars.name});
            
            
            % =============================================================
            % 2. DIRECT TOP-LEVEL CUBE FORMAT
            % =============================================================
            
            cubeCandidates = [ ...
                "Cube", ...
                "RamanCube", ...
                "cube", ...
                "checkedCube", ...
                "baselineCorrectedCube", ...
                "smoothedScanCube", ...
                "CorrectedCube", ...
                "CubeOriginal"];
            
            
            wnCandidates = [ ...
                "Wavenumber", ...
                "Wavenumbers", ...
                "wn", ...
                "scanWn", ...
                "checkedWn", ...
                "baselineCorrectedWn", ...
                "smoothedScanWn"];
            
            
            cubeFound = false;
            wnFound = false;
            
            
            for k = 1:numel(cubeCandidates)
                
                idx = ...
                    find( ...
                    varNames == cubeCandidates(k), ...
                    1);
                
                if isempty(idx)
                    continue
                end
                
                
                sz = ...
                    vars(idx).size;
                
                
                if numel(sz) == 3 && ...
                        all(sz > 0)
                    
                    cubeFound = true;
                    break
                    
                end
                
            end
            
            
            for k = 1:numel(wnCandidates)
                
                idx = ...
                    find( ...
                    varNames == wnCandidates(k), ...
                    1);
                
                if isempty(idx)
                    continue
                end
                
                
                if prod(vars(idx).size) > 1
                    
                    wnFound = true;
                    break
                    
                end
                
            end
            
            
            if cubeFound && wnFound
                
                tf = true;
                return
                
            end
            
            
            % =============================================================
            % 3. READABLE RAMAN FORMAT
            %
            % MAT:
            %     RamanData = 1x1 struct
            %
            % Expected:
            %     RamanData.Cube
            %     RamanData.Wavenumber
            % =============================================================
            
            if any(varNames == "RamanData")
                
                try
                    
                    S = ...
                        load( ...
                        matPath, ...
                        'RamanData');
                    
                    
                    if ~isfield(S, 'RamanData') || ...
                            ~isstruct(S.RamanData) || ...
                            numel(S.RamanData) ~= 1
                        
                        return
                        
                    end
                    
                    
                    R = ...
                        S.RamanData;
                    
                    
                    % ---------------------------------------------------------
                    % CUBE
                    % ---------------------------------------------------------
                    
                    cubeFound = false;
                    
                    
                    readableCubeFields = { ...
                        'Cube', ...
                        'RamanCube', ...
                        'CubeOriginal', ...
                        'RawCube', ...
                        'OriginalCube'};
                    
                    
                    for k = 1:numel(readableCubeFields)
                        
                        fieldName = ...
                            readableCubeFields{k};
                        
                        
                        if ~isfield(R, fieldName)
                            continue
                        end
                        
                        
                        candidate = ...
                            R.(fieldName);
                        
                        
                        if isnumeric(candidate) && ...
                                ndims(candidate) == 3 && ...
                                ~isempty(candidate)
                            
                            cubeFound = true;
                            break
                            
                        end
                        
                    end
                    
                    
                    % ---------------------------------------------------------
                    % WAVENUMBER
                    % ---------------------------------------------------------
                    
                    wnFound = false;
                    
                    
                    readableWNFields = { ...
                        'Wavenumber', ...
                        'Wavenumbers', ...
                        'wn'};
                    
                    
                    for k = 1:numel(readableWNFields)
                        
                        fieldName = ...
                            readableWNFields{k};
                        
                        
                        if ~isfield(R, fieldName)
                            continue
                        end
                        
                        
                        candidate = ...
                            R.(fieldName);
                        
                        
                        if isnumeric(candidate) && ...
                                isvector(candidate) && ...
                                numel(candidate) > 1
                            
                            wnFound = true;
                            break
                            
                        end
                        
                    end
                    
                    
                    tf = ...
                        cubeFound && wnFound;
                    
                    
                catch
                    
                    tf = false;
                    
                end
                
            end
            
        end


        function [cube, wn] = loadSpatialCubeFromMat(app, matPath) %#ok<INUSD>
            
            % =============================================================
            % LOAD A SPATIALLY RESOLVED RAMAN CUBE
            %
            % Supported direct variables:
            %   Cube
            %   RamanCube
            %   cube
            %
            % Supported wavenumber variables:
            %   Wavenumber
            %   Wavenumbers
            %   wn
            %
            % Also searches one level inside scalar structs.
            % =============================================================
            
            cube = [];
            wn = [];
            
            
            matPath = ...
                char(string(matPath));
            
            
            if isempty(matPath) || ...
                    ~isfile(matPath)
                
                error( ...
                    'single_mode:SpatialMATMissing', ...
                    ['Spatial Raman MAT file does not exist:' ...
                    newline newline ...
                    '%s'], ...
                    matPath);
                
            end
            
            
            try
                
                S = ...
                    load(matPath);
                
            catch ME
                
                error( ...
                    'single_mode:SpatialMATLoadFailed', ...
                    ['Could not load spatial Raman MAT:' ...
                    newline newline ...
                    '%s' ...
                    newline newline ...
                    '%s'], ...
                    matPath, ...
                    ME.message);
                
            end
            
            
            % =============================================================
            % 1. DIRECT VARIABLES
            % =============================================================
            
            cubeFields = { ...
                'Cube', ...
                'RamanCube', ...
                'cube'};
            
            
            wnFields = { ...
                'Wavenumber', ...
                'Wavenumbers', ...
                'wn'};
            
            
            for k = 1:numel(cubeFields)
                
                fieldName = ...
                    cubeFields{k};
                
                
                if isfield(S, fieldName) && ...
                        isnumeric(S.(fieldName)) && ...
                        ndims(S.(fieldName)) == 3 && ...
                        ~isempty(S.(fieldName))
                    
                    cube = ...
                        double(S.(fieldName));
                    
                    break
                    
                end
                
            end
            
            
            for k = 1:numel(wnFields)
                
                fieldName = ...
                    wnFields{k};
                
                
                if isfield(S, fieldName) && ...
                        isnumeric(S.(fieldName)) && ...
                        isvector(S.(fieldName)) && ...
                        ~isempty(S.(fieldName))
                    
                    wn = ...
                        double(S.(fieldName)(:));
                    
                    break
                    
                end
                
            end
            
            
            % =============================================================
            % 2. ONE LEVEL INSIDE SCALAR STRUCTS
            % =============================================================
            
            if isempty(cube) || ...
                    isempty(wn)
                
                topFields = ...
                    fieldnames(S);
                
                
                for topNumber = 1:numel(topFields)
                    
                    currentValue = ...
                        S.(topFields{topNumber});
                    
                    
                    if ~isstruct(currentValue) || ...
                            numel(currentValue) ~= 1
                        
                        continue
                        
                    end
                    
                    
                    if isempty(cube)
                        
                        for k = 1:numel(cubeFields)
                            
                            fieldName = ...
                                cubeFields{k};
                            
                            
                            if isfield(currentValue, fieldName) && ...
                                    isnumeric(currentValue.(fieldName)) && ...
                                    ndims(currentValue.(fieldName)) == 3 && ...
                                    ~isempty(currentValue.(fieldName))
                                
                                cube = ...
                                    double(currentValue.(fieldName));
                                
                                break
                                
                            end
                            
                        end
                        
                    end
                    
                    
                    if isempty(wn)
                        
                        for k = 1:numel(wnFields)
                            
                            fieldName = ...
                                wnFields{k};
                            
                            
                            if isfield(currentValue, fieldName) && ...
                                    isnumeric(currentValue.(fieldName)) && ...
                                    isvector(currentValue.(fieldName)) && ...
                                    ~isempty(currentValue.(fieldName))
                                
                                wn = ...
                                    double(currentValue.(fieldName)(:));
                                
                                break
                                
                            end
                            
                        end
                        
                    end
                    
                    
                    if ~isempty(cube) && ...
                            ~isempty(wn)
                        
                        break
                        
                    end
                    
                end
                
            end
            
            
            % =============================================================
            % 3. VALIDATION
            % =============================================================
            
            if isempty(cube)
                
                error( ...
                    'single_mode:SpatialCubeMissing', ...
                    ['No 3-D Raman cube could be found in:' ...
                    newline newline ...
                    '%s'], ...
                    matPath);
                
            end
            
            
            if isempty(wn)
                
                error( ...
                    'single_mode:SpatialWavenumberMissing', ...
                    ['No Raman wavenumber axis could be found in:' ...
                    newline newline ...
                    '%s'], ...
                    matPath);
                
            end
            
            
            if ndims(cube) ~= 3
                
                error( ...
                    'single_mode:SpatialCubeInvalid', ...
                    'Spatial Raman data must be a 3-D cube.');
                
            end
            
            
            if numel(wn) ~= size(cube, 3)
                
                error( ...
                    'single_mode:SpatialDimensionMismatch', ...
                    ['Spatial Raman cube and wavenumber axis do not match.' ...
                    newline newline ...
                    'Cube channels: %d' ...
                    newline ...
                    'Wavenumber values: %d' ...
                    newline newline ...
                    '%s'], ...
                    size(cube,3), ...
                    numel(wn), ...
                    matPath);
                
            end
            
            
            if isempty(cube) || ...
                    any(size(cube) == 0)
                
                error( ...
                    'single_mode:SpatialCubeEmpty', ...
                    'Spatial Raman cube is empty.');
                
            end
            
            
            wn = ...
                double(wn(:));
            
        end
        

        function info = resolveSpatialDatasetFromGroupFolder( ...
                app, ...
                groupFolder, ...
                groupName)
            
            % =========================================================
            % RESOLVE ONE SINGLE-SPECTRUM DATASET BACK TO ITS RAMAN SCAN
            %
            % Primary source:
            %   <groupFolder>\spatial_source.mat
            %
            % Current single_extract sidecar fields supported:
            %   ScanName
            %   ScanID
            %   ReadableMATPath
            %   OriginalSourceMatPath
            %   ClusterMATPath
            %   ClusterRunFolder
            %   ExtractionFolder
            %   RepresentativeMATPath
            %   OutputFolder
            %
            % Older compatibility fields also supported:
            %   SourceMatPath
            %   ScanOutputRoot
            %
            % No Raman cube is copied.
            % =========================================================
            
            
            % =========================================================
            % 1. EMPTY RESULT
            % =========================================================
            
            info = struct();
            
            info.Valid = false;
            
            info.DisplayName = string(groupName);
            info.SourceScanName = "";
            
            info.RawCubePath = "";
            info.ProcessedCubePath = "";
            info.WavenumberPath = "";
            info.MetadataPath = "";
            info.SourceSessionFolder = "";
            info.SourceStage = "";
            
            info.Ny = NaN;
            info.Nx = NaN;
            info.NWn = NaN;
            info.WnMin = NaN;
            info.WnMax = NaN;
            
            
            groupFolder = ...
                string(groupFolder);
            
            
            if strlength(groupFolder) == 0 || ...
                    ~isfolder(groupFolder)
                
                return
            end
            
            
            % =========================================================
            % 2. OFFICIAL spatial_source.mat
            % =========================================================
            
            sourceFile = ...
                fullfile( ...
                groupFolder, ...
                'spatial_source.mat');
            
            
            if isfile(sourceFile)
                
                try
                    
                    T = ...
                        load( ...
                        sourceFile, ...
                        'SpatialSource');
                    
                    
                    if isfield(T, 'SpatialSource') && ...
                            isstruct(T.SpatialSource)
                        
                        S = ...
                            T.SpatialSource;
                        
                        
                        % =================================================
                        % SOURCE SCAN NAME
                        % =================================================
                        
                        if isfield(S, 'ScanName') && ...
                                ~isempty(S.ScanName)
                            
                            info.SourceScanName = ...
                                string(S.ScanName);
                            
                        elseif isfield(S, 'ScanID') && ...
                                ~isempty(S.ScanID)
                            
                            info.SourceScanName = ...
                                string(S.ScanID);
                            
                        end
                        
                        
                        % =================================================
                        % PRIMARY CURRENT SPATIAL SOURCE
                        % =================================================
                        
                        if isfield(S, 'ProcessedCubePath') && ...
                                ~isempty(S.ProcessedCubePath)
                            
                            candidate = ...
                                string(S.ProcessedCubePath);
                            
                            if isfile(candidate) && ...
                                    app.isSpatialCubeMatFile(candidate)
                                
                                info.ProcessedCubePath = ...
                                    candidate;
                            end
                        end
                        
                        
                        % =================================================
                        % CURRENT FORMAT FALLBACK:
                        % ReadableMATPath
                        % =================================================
                        
                        if strlength(info.ProcessedCubePath) == 0 && ...
                                isfield(S, 'ReadableMATPath') && ...
                                ~isempty(S.ReadableMATPath)
                            
                            candidate = ...
                                string(S.ReadableMATPath);
                            
                            if isfile(candidate) && ...
                                    app.isSpatialCubeMatFile(candidate)
                                
                                info.ProcessedCubePath = ...
                                    candidate;
                                
                            end
                        end
                        
                        
                        % =================================================
                        % ORIGINAL SOURCE MAT FALLBACK
                        % =================================================
                        
                        if strlength(info.ProcessedCubePath) == 0 && ...
                                isfield(S, 'OriginalSourceMatPath') && ...
                                ~isempty(S.OriginalSourceMatPath)
                            
                            candidate = ...
                                string(S.OriginalSourceMatPath);
                            
                            if isfile(candidate) && ...
                                    app.isSpatialCubeMatFile(candidate)
                                
                                info.ProcessedCubePath = ...
                                    candidate;
                                
                            end
                        end
                        
                        
                        % =================================================
                        % LEGACY FIELD:
                        % SourceMatPath
                        % =================================================
                        
                        if strlength(info.ProcessedCubePath) == 0 && ...
                                isfield(S, 'SourceMatPath') && ...
                                ~isempty(S.SourceMatPath)
                            
                            candidate = ...
                                string(S.SourceMatPath);
                            
                            if isfile(candidate) && ...
                                    app.isSpatialCubeMatFile(candidate)
                                
                                info.ProcessedCubePath = ...
                                    candidate;
                                
                            end
                        end
                        
                        
                        % =================================================
                        % SOURCE SESSION / EXTRACTION LOCATION
                        % =================================================
                        
                        if isfield(S, 'ClusterRunFolder') && ...
                                ~isempty(S.ClusterRunFolder)
                            
                            candidateFolder = ...
                                string(S.ClusterRunFolder);
                            
                            
                            if isfolder(candidateFolder)
                                
                                info.SourceSessionFolder = ...
                                    candidateFolder;
                                
                            end
                            
                        elseif isfield(S, 'ExtractionFolder') && ...
                                ~isempty(S.ExtractionFolder)
                            
                            candidateFolder = ...
                                string(S.ExtractionFolder);
                            
                            
                            if isfolder(candidateFolder)
                                
                                info.SourceSessionFolder = ...
                                    candidateFolder;
                                
                            end
                            
                        elseif isfield(S, 'ScanOutputRoot') && ...
                                ~isempty(S.ScanOutputRoot)
                            
                            candidateFolder = ...
                                string(S.ScanOutputRoot);
                            
                            
                            if isfolder(candidateFolder)
                                
                                info.SourceSessionFolder = ...
                                    candidateFolder;
                                
                            end
                            
                        end
                        
                        
                        info.MetadataPath = ...
                            string(sourceFile);
                        
                        
                        info.SourceStage = ...
                            "single_extract";
                        
                    end
                    
                catch ME
                    
                    app.logActivity( ...
                        "Spatial distribution", ...
                        "Read spatial_source.mat", ...
                        string(groupName), ...
                        "WARNING", ...
                        string(ME.message));
                    
                end
                
            end
            
            
            % =========================================================
            % 3. COMPATIBILITY SEARCH IN OTHER MAT METADATA
            %
            % Only used if spatial_source.mat was absent/incomplete.
            % =========================================================
            
            if strlength(info.SourceScanName) == 0 || ...
                    strlength(info.ProcessedCubePath) == 0
                
                matFiles = ...
                    dir( ...
                    fullfile( ...
                    groupFolder, ...
                    '*.mat'));
                
                
                for k = 1:numel(matFiles)
                    
                    fp = ...
                        string( ...
                        fullfile( ...
                        matFiles(k).folder, ...
                        matFiles(k).name));
                    
                    
                    % Do not unnecessarily re-read spatial_source.mat.
                    if strcmpi( ...
                            string(matFiles(k).name), ...
                            "spatial_source.mat")
                        
                        continue
                    end
                    
                    
                    try
                        
                        vars = ...
                            whos( ...
                            '-file', ...
                            fp);
                        
                        varNames = ...
                            string({vars.name});
                        
                    catch
                        
                        continue
                        
                    end
                    
                    
                    possibleNames = [ ...
                        "metadata", ...
                        "Metadata", ...
                        "Provenance", ...
                        "SourceInfo", ...
                        "DatasetInfo", ...
                        "SpatialSource"];
                    
                    
                    availableNames = ...
                        intersect( ...
                        varNames, ...
                        possibleNames, ...
                        'stable');
                    
                    
                    if isempty(availableNames)
                        continue
                    end
                    
                    
                    try
                        
                        temp = ...
                            load( ...
                            fp, ...
                            availableNames{:});
                        
                    catch
                        
                        continue
                    end
                    
                    
                    fieldsLoaded = ...
                        fieldnames(temp);
                    
                    
                    for m = 1:numel(fieldsLoaded)
                        
                        M = ...
                            temp.(fieldsLoaded{m});
                        
                        
                        if ~isstruct(M)
                            continue
                        end
                        
                        
                        % =================================================
                        % FLAT SCAN NAME
                        % =================================================
                        
                        if strlength(info.SourceScanName) == 0
                            
                            if isfield(M, 'ScanName') && ...
                                    ~isempty(M.ScanName)
                                
                                info.SourceScanName = ...
                                    string(M.ScanName);
                                
                            elseif isfield(M, 'ScanID') && ...
                                    ~isempty(M.ScanID)
                                
                                info.SourceScanName = ...
                                    string(M.ScanID);
                                
                            end
                            
                        end
                        
                        
                        % =================================================
                        % CURRENT ReadableMATPath
                        % =================================================
                        
                        if strlength(info.ProcessedCubePath) == 0 && ...
                                isfield(M, 'ReadableMATPath') && ...
                                ~isempty(M.ReadableMATPath)
                            
                            candidate = ...
                                string(M.ReadableMATPath);
                            
                            
                            if isfile(candidate) && ...
                                    app.isSpatialCubeMatFile(candidate)
                                
                                info.ProcessedCubePath = ...
                                    candidate;
                                
                            end
                            
                        end
                        
                        
                        % =================================================
                        % LEGACY SourceMatPath
                        % =================================================
                        
                        if strlength(info.ProcessedCubePath) == 0 && ...
                                isfield(M, 'SourceMatPath') && ...
                                ~isempty(M.SourceMatPath)
                            
                            candidate = ...
                                string(M.SourceMatPath);
                            
                            
                            if isfile(candidate) && ...
                                    app.isSpatialCubeMatFile(candidate)
                                
                                info.ProcessedCubePath = ...
                                    candidate;
                                
                            end
                            
                        end
                        
                        
                        % =================================================
                        % NESTED metadata.Scan
                        % =================================================
                        
                        if isfield(M, 'Scan') && ...
                                isstruct(M.Scan)
                            
                            scanInfo = ...
                                M.Scan;
                            
                            
                            if strlength(info.SourceScanName) == 0
                                
                                if isfield(scanInfo, 'Name') && ...
                                        ~isempty(scanInfo.Name)
                                    
                                    info.SourceScanName = ...
                                        string(scanInfo.Name);
                                    
                                elseif isfield(scanInfo, 'ID') && ...
                                        ~isempty(scanInfo.ID)
                                    
                                    info.SourceScanName = ...
                                        string(scanInfo.ID);
                                    
                                end
                                
                            end
                            
                            
                            if strlength(info.ProcessedCubePath) == 0 && ...
                                    isfield(scanInfo, 'SourceMatPath') && ...
                                    ~isempty(scanInfo.SourceMatPath)
                                
                                candidate = ...
                                    string(scanInfo.SourceMatPath);
                                
                                
                                if isfile(candidate) && ...
                                        app.isSpatialCubeMatFile(candidate)
                                    
                                    info.ProcessedCubePath = ...
                                        candidate;
                                    
                                end
                                
                            end
                            
                            
                            if strlength(info.SourceSessionFolder) == 0 && ...
                                    isfield(scanInfo, 'OutputRoot') && ...
                                    ~isempty(scanInfo.OutputRoot)
                                
                                candidateFolder = ...
                                    string(scanInfo.OutputRoot);
                                
                                
                                if isfolder(candidateFolder)
                                    
                                    info.SourceSessionFolder = ...
                                        candidateFolder;
                                    
                                end
                                
                            end
                            
                        end
                        
                        
                        if strlength(info.MetadataPath) == 0
                            
                            info.MetadataPath = ...
                                fp;
                            
                        end
                        
                    end
                    
                end
                
            end
            
            
            % =========================================================
            % 4. LEGACY TXT PROVENANCE
            % =========================================================
            
            if strlength(info.SourceScanName) == 0 || ...
                    strlength(info.ProcessedCubePath) == 0
                
                txtCandidates = [ ...
                    dir(fullfile(groupFolder, '*provenance*.txt')); ...
                    dir(fullfile(groupFolder, '*metadata*.txt')); ...
                    dir(fullfile(groupFolder, '*manifest*.txt'))];
                
                
                for k = 1:numel(txtCandidates)
                    
                    fp = ...
                        string( ...
                        fullfile( ...
                        txtCandidates(k).folder, ...
                        txtCandidates(k).name));
                    
                    
                    try
                        
                        textData = ...
                            string(fileread(fp));
                        
                    catch
                        
                        continue
                    end
                    
                    
                    if strlength(info.SourceScanName) == 0
                        
                        token = ...
                            regexp( ...
                            textData, ...
                            '(?:Scan name|Scan Name|Scan):\s*(.+)', ...
                            'tokens', ...
                            'once');
                        
                        
                        if ~isempty(token)
                            
                            info.SourceScanName = ...
                                strtrim( ...
                                string(token{1}));
                            
                        end
                        
                    end
                    
                    
                    if strlength(info.ProcessedCubePath) == 0
                        
                        token = ...
                            regexp( ...
                            textData, ...
                            ['(?:Readable MAT|MAT path|Source MAT|' ...
                            'Source file|SourceMatPath):\s*(.+)'], ...
                            'tokens', ...
                            'once');
                        
                        
                        if ~isempty(token)
                            
                            candidate = ...
                                strtrim( ...
                                string(token{1}));
                            
                            
                            if isfile(candidate) && ...
                                    app.isSpatialCubeMatFile(candidate)
                                
                                info.ProcessedCubePath = ...
                                    candidate;
                                
                            end
                            
                        end
                        
                    end
                    
                    
                    if strlength(info.MetadataPath) == 0
                        
                        info.MetadataPath = ...
                            fp;
                        
                    end
                    
                end
                
            end
            
            
            % =========================================================
            % 5. SESSION / PROJECT RELOCATION FALLBACK
            %
            % Primary reconstruction target:
            %
            %   <ProjectRoot>\Data input\scan\READABLE\
            %       <ScanName>\<ScanName>_RamanData.mat
            %
            % This makes spatial provenance relocatable if the project
            % is moved to another drive/computer.
            % =========================================================
            
            if strlength(info.SourceScanName) > 0 && ...
                    strlength(info.ProcessedCubePath) == 0
                
                readableRoot = "";
                
                % ---------------------------------------------------------
                % 5A. Prefer the explicit scan-input root supplied by Home
                % ---------------------------------------------------------
                if isstruct(app.homeSession)
                    
                    if isfield(app.homeSession, 'ScanInputRoot') && ...
                            strlength( ...
                            string(app.homeSession.ScanInputRoot)) > 0
                        
                        scanInputRoot = ...
                            string(app.homeSession.ScanInputRoot);
                        
                        candidateReadableRoot = ...
                            fullfile( ...
                            scanInputRoot, ...
                            'READABLE');
                        
                        if isfolder(candidateReadableRoot)
                            
                            readableRoot = ...
                                candidateReadableRoot;
                            
                        elseif isfolder(scanInputRoot)
                            
                            % Compatibility in case ScanInputRoot itself
                            % already points to READABLE.
                            [~, lastPart] = ...
                                fileparts( ...
                                char(scanInputRoot));
                            
                            if strcmpi(lastPart, 'READABLE')
                                
                                readableRoot = ...
                                    scanInputRoot;
                            end
                        end
                    end
                    
                    
                    % ---------------------------------------------------------
                    % 5B. Reconstruct from ProjectRoot
                    % ---------------------------------------------------------
                    if strlength(readableRoot) == 0 && ...
                            isfield(app.homeSession, 'ProjectRoot') && ...
                            strlength( ...
                            string(app.homeSession.ProjectRoot)) > 0
                        
                        candidateReadableRoot = ...
                            fullfile( ...
                            string(app.homeSession.ProjectRoot), ...
                            'Data input', ...
                            'scan', ...
                            'READABLE');
                        
                        if isfolder(candidateReadableRoot)
                            
                            readableRoot = ...
                                candidateReadableRoot;
                        end
                    end
                    
                end
                
                
                % ---------------------------------------------------------
                % 5C. Exact canonical READABLE file
                % ---------------------------------------------------------
                if strlength(readableRoot) > 0 && ...
                        isfolder(readableRoot)
                    
                    safeScanName = ...
                        string(info.SourceScanName);
                    
                    exactCandidate = ...
                        fullfile( ...
                        readableRoot, ...
                        safeScanName, ...
                        safeScanName + "_RamanData.mat");
                    
                    
                    if isfile(exactCandidate) && ...
                            app.isSpatialCubeMatFile(exactCandidate)
                        
                        info.ProcessedCubePath = ...
                            string(exactCandidate);
                        
                    else
                        
                        % -----------------------------------------------------
                        % Compatibility search if metadata name differs slightly
                        % -----------------------------------------------------
                        candidate = ...
                            app.findSpatialCubeForScan( ...
                            readableRoot, ...
                            info.SourceScanName);
                        
                        if strlength(candidate) > 0 && ...
                                isfile(candidate) && ...
                                app.isSpatialCubeMatFile(candidate)
                            
                            info.ProcessedCubePath = ...
                                candidate;
                        end
                    end
                end
            end
            
            
            % =========================================================
            % 6. FINAL SOURCE VALIDATION
            % =========================================================
            
            if strlength(info.ProcessedCubePath) > 0
                
                if ~isfile(info.ProcessedCubePath) || ...
                        ~app.isSpatialCubeMatFile( ...
                        info.ProcessedCubePath)
                    
                    info.ProcessedCubePath = "";
                    
                end
                
            end
            
            
            if strlength(info.RawCubePath) > 0
                
                if ~isfile(info.RawCubePath) || ...
                        ~app.isSpatialCubeMatFile( ...
                        info.RawCubePath)
                    
                    info.RawCubePath = "";
                    
                end
                
            end
            
            
            % =========================================================
            % 7. MUST HAVE AN ACTUAL SPATIAL CUBE
            % =========================================================
            
            if strlength(info.ProcessedCubePath) == 0 && ...
                    strlength(info.RawCubePath) == 0
                
                return
                
            end
            
            
            % =========================================================
            % 8. FALLBACK SOURCE NAME FROM FILE
            % =========================================================
            
            if strlength(info.SourceScanName) == 0
                
                sourcePath = ...
                    app.firstNonEmptyString( ...
                    info.ProcessedCubePath, ...
                    info.RawCubePath);
                
                
                [~, nm] = ...
                    fileparts( ...
                    char(sourcePath));
                
                
                info.SourceScanName = ...
                    string(nm);
                
            end
            
            
            % =========================================================
            % 9. READ CUBE INFORMATION FOR REGISTRY
            % =========================================================
            
            sourcePath = ...
                app.firstNonEmptyString( ...
                info.ProcessedCubePath, ...
                info.RawCubePath);
            
            
            try
                
                [cube, wn] = ...
                    app.loadSpatialCubeFromMat( ...
                    sourcePath);
                
                
                info.Ny = ...
                    size(cube, 1);
                
                info.Nx = ...
                    size(cube, 2);
                
                info.NWn = ...
                    size(cube, 3);
                
                
                if ~isempty(wn)
                    
                    wn = ...
                        double(wn(:));
                    
                    info.WnMin = ...
                        min(wn, [], 'omitnan');
                    
                    info.WnMax = ...
                        max(wn, [], 'omitnan');
                    
                end
                
                
                clear cube
                
            catch
                
                % A path may pass the lightweight MAT test but fail the
                % actual cube loader. Such a dataset must not enter registry.
                return
                
            end
            
            
            % =========================================================
            % 10. VALID DATASET
            % =========================================================
            
            info.DisplayName = ...
                info.SourceScanName;
            
            
            info.Valid = ...
                true;
            
        end


        function filePath = findSpatialCubeForScan( ...
                app, ...
                scanRoot, ...
                scanName)
            
            filePath = "";
            
            scanRoot = string(scanRoot);
            scanName = string(scanName);
            
            
            if ~isfolder(scanRoot) || ...
                    strlength(scanName) == 0
                
                return
            end
            
            
            % =========================================================
            % Search only MAT files.
            % =========================================================
            files = dir( ...
                fullfile( ...
                scanRoot, ...
                '**', ...
                '*.mat'));
            
            
            if isempty(files)
                return
            end
            
            
            % =========================================================
            % Prefer files whose path contains exact scan name.
            % =========================================================
            paths = strings(numel(files),1);
            
            for k = 1:numel(files)
                
                paths(k) = ...
                    string(fullfile( ...
                    files(k).folder, ...
                    files(k).name));
                
            end
            
            
            match = contains( ...
                lower(paths), ...
                lower(scanName));
            
            
            candidatePaths = ...
                paths(match);
            
            
            if isempty(candidatePaths)
                return
            end
            
            
            % =========================================================
            % Prefer processed/final/current scan data.
            % =========================================================
            score = zeros(numel(candidatePaths),1);
            
            for k = 1:numel(candidatePaths)
                
                p = lower(candidatePaths(k));
                
                if contains(p, "processed")
                    score(k) = score(k) + 10;
                end
                
                if contains(p, "final")
                    score(k) = score(k) + 8;
                end
                
                if contains(p, "artifact")
                    score(k) = score(k) + 5;
                end
                
                if contains(p, "cosmic")
                    score(k) = score(k) + 3;
                end
                
                if contains(p, "raw")
                    score(k) = score(k) - 2;
                end
                
            end
            
            
            [~, idx] = max(score);
            
            filePath = ...
                candidatePaths(idx);
            
        end


        function refreshSpatialDatasetDropdowns(app)
            
            dropdowns = ...
                app.getSpatialDatasetDropdowns();
            
            
            if isempty(app.spatialDatasets)
                
                items = {''};
                
            else
                
                names = string( ...
                    {app.spatialDatasets.DisplayName});
                
                items = ...
                    cellstr([""; names(:)]);
                
            end
            
            
            for k = 1:numel(dropdowns)
                
                dd = dropdowns{k};
                
                oldValue = string(dd.Value);
                
                dd.Items = items;
                
                
                if any(string(items) == oldValue)
                    
                    dd.Value = char(oldValue);
                    
                else
                    
                    dd.Value = '';
                    
                end
                
            end
            
        end


        function handleSpatialDatasetSelection( ...
                app, ...
                panelNumber)
            
            dropdowns = ...
                app.getSpatialDatasetDropdowns();
            
            axesList = ...
                app.getSpatialAxes();
            
            
            dd = dropdowns{panelNumber};
            
            ax = axesList{panelNumber};
            
            selectedName = ...
                string(dd.Value);
            
            
            % =========================================================
            % EMPTY SELECTION
            % =========================================================
            if strlength(selectedName) == 0
                
                app.clearSpatialPanel(panelNumber);
                
                cla(ax, 'reset');
                
                title( ...
                    ax, ...
                    sprintf( ...
                    'Panel %d — select dataset', ...
                    panelNumber), ...
                    'Interpreter', ...
                    'none');
                
                return
            end
            
            
            % =========================================================
            % RESOLVE DATASET
            % =========================================================
            idx = find( ...
                string({app.spatialDatasets.DisplayName}) == ...
                selectedName, ...
                1);
            
            
            if isempty(idx)
                
                uialert( ...
                    app.UIFigure, ...
                    'Selected spatial dataset cannot be resolved.', ...
                    'Spatial dataset');
                
                return
            end
            
            
            D = ...
                app.spatialDatasets(idx);
            
            
            % =========================================================
            % INVALIDATE PREVIOUS MAP
            % =========================================================
            app.clearSpatialPanel(panelNumber);
            
            app.spatialPanels(panelNumber).DatasetID = ...
                string(D.ID);
            
            app.spatialPanels(panelNumber).DatasetName = ...
                string(D.DisplayName);
            
            
            % =========================================================
            % LOAD DATASET ON DEMAND
            % =========================================================
            try
                
                [cubeData, wn] = ...
                    app.loadSpatialDatasetCube( ...
                    D.ID, ...
                    "Processed");
                
            catch ME
                
                uialert( ...
                    app.UIFigure, ...
                    sprintf( ...
                    ['Cannot load spatial dataset.\n\n' ...
                    '%s'], ...
                    ME.message), ...
                    'Spatial dataset');
                
                return
            end
            
            
            % =========================================================
            % REFERENCE BAND
            % =========================================================
            refMap = ...
                app.calculateSimpleIntegratedMap( ...
                cubeData, ...
                wn, ...
                app.spatialReferenceBand(1), ...
                app.spatialReferenceBand(2));
            
            
            app.spatialPanels(panelNumber).ReferenceData = ...
                refMap;
            
            
            % =========================================================
            % DISPLAY GRAYSCALE REFERENCE
            % =========================================================
            app.plotSpatialReference( ...
                ax, ...
                refMap, ...
                D.DisplayName);
            
        end


        function tf = isPairwiseSpectralType(app)
            
            spectralType = ...
                string(app.SpectraltypesDropDown.Value);
            
            tf = any(strcmp( ...
                spectralType, ...
                [ ...
                "Mean first derivative", ...
                "Mean second derivative", ...
                "Mean difference" ...
                ]));
            
        end


        function clearSpatialPanel(app, panelNumber)
            
            oldID = ...
                app.spatialPanels(panelNumber).PanelID;
            
            app.spatialPanels(panelNumber) = ...
                struct( ...
                'PanelID', oldID, ...
                'DatasetID', "", ...
                'DatasetName', "", ...
                'Recipe', struct(), ...
                'MapData', [], ...
                'ReferenceData', [], ...
                'MapTitle', "", ...
                'MapUnit', "", ...
                'Calculated', false, ...
                'CalculatedAt', "", ...
                'CLim', [NaN NaN]);
            
        end
        
        
        function [cubeData, wn] = ...
                loadSpatialDatasetCube( ...
                app, ...
                datasetID, ...
                sourceMode)
            
            datasetID = string(datasetID);
            sourceMode = string(sourceMode);
            
            
            % =========================================================
            % 1. FIND DATASET
            % =========================================================
            idx = find( ...
                string({app.spatialDatasets.ID}) == ...
                datasetID, ...
                1);
            
            
            if isempty(idx)
                
                error( ...
                    'Spatial dataset ID not found: %s', ...
                    datasetID);
                
            end
            
            
            D = ...
                app.spatialDatasets(idx);
            
            
            % =========================================================
            % 2. REUSE CACHE
            % =========================================================
            if string(app.spatialCache.DatasetID) == datasetID
                
                wn = ...
                    app.spatialCache.Wn;
                
                if sourceMode == "Raw" && ...
                        ~isempty(app.spatialCache.RawCube)
                    
                    cubeData = ...
                        app.spatialCache.RawCube;
                    
                    return
                    
                elseif sourceMode == "Processed" && ...
                        ~isempty(app.spatialCache.ProcessedCube)
                    
                    cubeData = ...
                        app.spatialCache.ProcessedCube;
                    
                    return
                end
            end
            
            
            % =========================================================
            % 3. CLEAR OLD CACHE
            % =========================================================
            app.spatialCache = struct( ...
                'DatasetID', datasetID, ...
                'RawCube', [], ...
                'ProcessedCube', [], ...
                'Wn', [], ...
                'LoadedAt', string(datetime('now')));
            
            
            % =========================================================
            % 4. RESOLVE FILE PATH
            % =========================================================
            switch sourceMode
                
                case "Raw"
                    
                    filePath = ...
                        string(D.RawCubePath);
                    
                    if strlength(filePath) == 0
                        
                        % Safe fallback to processed only when raw
                        % provenance is unavailable.
                        filePath = ...
                            string(D.ProcessedCubePath);
                        
                    end
                    
                otherwise
                    
                    filePath = ...
                        string(D.ProcessedCubePath);
                    
                    if strlength(filePath) == 0
                        
                        filePath = ...
                            string(D.RawCubePath);
                        
                    end
            end
            
            
            if strlength(filePath) == 0 || ...
                    ~isfile(filePath)
                
                error( ...
                    ['Spatial cube file cannot be found.' newline ...
                    'Dataset: %s' newline ...
                    'Requested source: %s'], ...
                    D.DisplayName, ...
                    sourceMode);
            end
            
            
            % =========================================================
            % 5. LOAD SPATIAL CUBE USING THE CANONICAL LOADER
            %
            % IMPORTANT:
            % The READABLE Raman MAT normally stores:
            %
            %   RamanData.Cube
            %   RamanData.Wavenumber
            %
            % loadSpatialCubeFromMat() supports both direct variables
            % and one-level scalar structures such as RamanData.
            % =========================================================
            
            [cubeData, wn] = ...
                app.loadSpatialCubeFromMat( ...
                filePath);
            
            
            % =========================================================
            % 6. VALIDATE
            % =========================================================
            if isempty(cubeData) || ...
                    ndims(cubeData) ~= 3
                
                error( ...
                    'Spatial MAT does not contain a valid 3-D Raman cube.');
                
            end
            
            
            if isempty(wn) || ...
                    numel(wn) ~= size(cubeData,3)
                
                error( ...
                    ['Wavenumber vector is missing or does not match ' ...
                    'the spectral dimension of the cube.']);
                
            end
            
            
            % =========================================================
            % 7. CACHE
            % =========================================================
            app.spatialCache.Wn = ...
                wn(:);
            
            
            if sourceMode == "Raw"
                
                app.spatialCache.RawCube = ...
                    cubeData;
                
            else
                
                app.spatialCache.ProcessedCube = ...
                    cubeData;
                
            end
            
        end
        
        
        function [cubeData, wn] = ...
                loadCubeAndWavenumberFromMat( ...
                app, ...
                filePath)
            
            cubeData = [];
            wn = [];
            
            filePath = char(filePath);
            
            
            vars = whos('-file', filePath);
            
            varNames = string({vars.name});
            
            
            % =========================================================
            % WN CANDIDATES
            % =========================================================
            wnCandidates = [ ...
                "Wavenumber", ...
                "wn", ...
                "scanWn", ...
                "checkedWn", ...
                "baselineCorrectedWn", ...
                "smoothedScanWn"];
            
            
            for name = wnCandidates
                
                if any(varNames == name)
                    
                    temp = load( ...
                        filePath, ...
                        char(name));
                    
                    wn = ...
                        double(temp.(char(name)));
                    
                    wn = wn(:);
                    
                    break
                end
            end
            
            
            % =========================================================
            % CUBE CANDIDATES
            % =========================================================
            cubeCandidates = [ ...
                "RamanCube", ...
                "cube", ...
                "Cube", ...
                "checkedCube", ...
                "baselineCorrectedCube", ...
                "smoothedScanCube", ...
                "CorrectedCube", ...
                "CubeOriginal"];
            
            
            for name = cubeCandidates
                
                if any(varNames == name)
                    
                    temp = load( ...
                        filePath, ...
                        char(name));
                    
                    candidate = ...
                        temp.(char(name));
                    
                    if isnumeric(candidate) && ...
                            ndims(candidate) == 3
                        
                        cubeData = ...
                            candidate;
                        
                        break
                    end
                end
            end
            
            
            % =========================================================
            % FALLBACK: FIND FIRST NUMERIC 3-D VARIABLE
            % =========================================================
            if isempty(cubeData)
                
                for k = 1:numel(vars)
                    
                    sz = vars(k).size;
                    
                    if numel(sz) == 3 && ...
                            prod(sz) > 0
                        
                        temp = load( ...
                            filePath, ...
                            vars(k).name);
                        
                        candidate = ...
                            temp.(vars(k).name);
                        
                        if isnumeric(candidate)
                            
                            cubeData = candidate;
                            break
                        end
                    end
                end
            end
            
        end


        function map2D = calculateSimpleIntegratedMap( ...
                app, ...
                cubeData, ...
                wn, ...
                wnLow, ...
                wnHigh)
            
            wn = double(wn(:));
            
            lo = min(wnLow, wnHigh);
            hi = max(wnLow, wnHigh);
            
            mask = ...
                wn >= lo & ...
                wn <= hi;
            
            
            if ~any(mask)
                
                [~, idx] = ...
                    min(abs( ...
                    wn - mean([lo hi])));
                
                map2D = ...
                    double(cubeData(:,:,idx));
                
                return
            end
            
            
            % Integrated intensity by trapezoidal integration.
            %
            % Rearrange spectral dimension temporarily:
            % Y x X x WN
            %
            map2D = ...
                trapz( ...
                wn(mask), ...
                double(cubeData(:,:,mask)), ...
                3);
            
        end
        
        
        function plotSpatialReference( ...
                app, ...
                ax, ...
                referenceMap, ...
                datasetName)
            
            cla(ax, 'reset');
            
            
            img = ...
                imagesc( ...
                ax, ...
                referenceMap);
            
            if isprop(img, 'Interpolation')
                img.Interpolation = 'nearest';
            end
            
            
            axis(ax, 'image');
            axis(ax, 'tight');
            
            colormap(ax, gray);
            
            
            vals = ...
                double(referenceMap(:));
            
            vals = ...
                vals(isfinite(vals));
            
            
            if ~isempty(vals)
                
                lo = prctile(vals, 1);
                hi = prctile(vals, 99);
                
                if isfinite(lo) && ...
                        isfinite(hi) && ...
                        hi > lo
                    
                    clim(ax, [lo hi]);
                    
                end
            end
            
            
            xlabel(ax, 'X pixel');
            ylabel(ax, 'Y pixel');
            
            title( ...
                ax, ...
                sprintf( ...
                '%s | reference %.0f–%.0f cm^{-1}', ...
                datasetName, ...
                app.spatialReferenceBand(1), ...
                app.spatialReferenceBand(2)), ...
                'Interpreter', ...
                'none');
            
            ax.Box = 'on';
            
        end


        function map2D = ...
                spatialPeakPositionMap( ...
                app, ...
                cubeData, ...
                wn, ...
                band)
            
            %#ok<INUSD>
            
            mask = ...
                wn >= band(1) & ...
                wn <= band(2);
            
            
            x = ...
                wn(mask);
            
            
            Y = ...
                double(cubeData(:,:,mask));
            
            
            [~, idx] = ...
                max(Y, [], 3);
            
            
            map2D = ...
                x(idx);
            
        end
        
        
        function map2D = ...
                spatialFWHMMap( ...
                app, ...
                cubeData, ...
                wn, ...
                band)
            
            mask = ...
                wn >= band(1) & ...
                wn <= band(2);
            
            
            x = ...
                wn(mask);
            
            
            Y = ...
                double(cubeData(:,:,mask));
            
            
            [ny,nx,~] = ...
                size(Y);
            
            
            map2D = ...
                nan(ny,nx);
            
            
            for iy = 1:ny
                
                for ix = 1:nx
                    
                    invFwhm = ...
                        app.computeInverseFWHMSingle( ...
                        x, ...
                        squeeze(Y(iy,ix,:)));
                    
                    
                    if isfinite(invFwhm) && invFwhm > 0
                        
                        map2D(iy,ix) = ...
                            1 / invFwhm;
                        
                    end
                    
                end
                
            end
            
        end
        
        
        function [map2D, unitText] = ...
                spatialSimilarityMap( ...
                app, ...
                cubeData, ...
                recipe)
            
            %#ok<INUSD>
            
            [ny,nx,nw] = ...
                size(cubeData);
            
            
            X = ...
                reshape( ...
                double(cubeData), ...
                ny*nx, ...
                nw);
            
            
            if recipe.ReferenceMode == "Mean scan spectrum"
                
                ref = ...
                    mean(X,1,'omitnan');
                
                
            else
                
                pixel = ...
                    recipe.ReferencePixel;
                
                
                iy = ...
                    max(1,min(ny,pixel(1)));
                
                ix = ...
                    max(1,min(nx,pixel(2)));
                
                
                ref = ...
                    reshape( ...
                    double(cubeData(iy,ix,:)), ...
                    1, ...
                    []);
                
            end
            
            
            mapType = ...
                string(recipe.MapType);
            
            
            switch mapType
                
                case "Cosine similarity"
                    
                    numerator = ...
                        X * ref';
                    
                    
                    denominator = ...
                        sqrt(sum(X.^2,2)) .* ...
                        sqrt(sum(ref.^2));
                    
                    
                    values = ...
                        numerator ./ denominator;
                    
                    
                    unitText = ...
                        "Cosine similarity";
                    
                    
                case "Pearson similarity"
                    
                    Xc = ...
                        X - mean(X,2,'omitnan');
                    
                    
                    refc = ...
                        ref - mean(ref,'omitnan');
                    
                    
                    numerator = ...
                        Xc * refc';
                    
                    
                    denominator = ...
                        sqrt(sum(Xc.^2,2)) .* ...
                        sqrt(sum(refc.^2));
                    
                    
                    values = ...
                        numerator ./ denominator;
                    
                    
                    unitText = ...
                        "Pearson r";
                    
                    
                case "Spectral angle"
                    
                    numerator = ...
                        X * ref';
                    
                    
                    denominator = ...
                        sqrt(sum(X.^2,2)) .* ...
                        sqrt(sum(ref.^2));
                    
                    
                    cosineValue = ...
                        numerator ./ denominator;
                    
                    
                    cosineValue = ...
                        max(-1,min(1,cosineValue));
                    
                    
                    values = ...
                        acos(cosineValue);
                    
                    
                    unitText = ...
                        "Spectral angle (rad)";
                    
            end
            
            
            values(~isfinite(values)) = NaN;
            
            
            map2D = ...
                reshape(values,ny,nx);
            
        end


        function map2D = ...
                spatialPCAScoreMap( ...
                app, ...
                cubeData, ...
                wn, ...
                pc)
            
            if app.pcaInputType ~= "Spectra"
                
                error( ...
                    'Spatial PCA score requires a spectral PCA model.');
                
            end
            
            
            targetWn = ...
                app.pcaWn(:);
            
            
            [ny,nx,nw] = ...
                size(cubeData); %#ok<ASGLU>
            
            
            X = ...
                reshape( ...
                double(cubeData), ...
                ny*nx, ...
                []);
            
            
            % =========================================================
            % Interpolate scan spectra onto PCA variable axis.
            % =========================================================
            
            Xinterp = ...
                interp1( ...
                double(wn(:)), ...
                X.', ...
                targetWn, ...
                'linear', ...
                NaN).';
            
            
            % Apply same normalization used by PCA.
            Xwork = ...
                app.normalizeClassificationMatrix( ...
                Xinterp, ...
                targetWn, ...
                app.pcaNormMethod);
            
            
            center = ...
                app.pcaCenter;
            
            
            if isempty(center) || ...
                    numel(center) ~= size(Xwork,2)
                
                error( ...
                    ['PCA center vector is unavailable. ' ...
                    'Re-run PCA in the current session.']);
                
            end
            
            
            scoreValue = ...
                (Xwork - center) * ...
                app.pcaLoadings(:,pc);
            
            
            map2D = ...
                reshape( ...
                scoreValue, ...
                ny, ...
                nx);
            
        end
        
        
        function map2D = ...
                spatialClassMap( ...
                app, ...
                cubeData, ...
                wn)
            
            if isempty(app.classModel)
                
                error( ...
                    'No classification model is available.');
                
            end
            
            
            targetWn = ...
                app.classWn(:);
            
            
            [ny,nx,~] = ...
                size(cubeData);
            
            
            X = ...
                reshape( ...
                double(cubeData), ...
                ny*nx, ...
                []);
            
            
            Xinterp = ...
                interp1( ...
                double(wn(:)), ...
                X.', ...
                targetWn, ...
                'linear', ...
                NaN).';
            
            
            Xwork = ...
                app.normalizeClassificationMatrix( ...
                Xinterp, ...
                targetWn, ...
                app.classNormMethod);
            
            
            valid = ...
                all(isfinite(Xwork),2);
            
            
            classCode = ...
                nan(size(Xwork,1),1);
            
            
            if any(valid)
                
                validIdx = ...
                    find(valid);
                
                
                pred = ...
                    predict( ...
                    app.classModel, ...
                    Xwork(valid,:));
                
                
                pred = ...
                    string(pred);
                
                
                for g = 1:numel(app.classGroupNames)
                    
                    localMask = ...
                        pred == ...
                        app.classGroupNames(g);
                    
                    
                    classCode( ...
                        validIdx(localMask)) = ...
                        g;
                    
                end
                
            end
            
            
            map2D = ...
                reshape( ...
                classCode, ...
                ny, ...
                nx);
            
        end
        
        
        function [recipe, ok] = ...
                askSpatialMapRecipe(app)
            
            ok = false;
            
            
            mapType = ...
                string(app.DisplaymaptypeDropDown.Value);
            
            
            mapSource = ...
                string(app.MapsourceDropDown.Value);
            
            
            recipe = struct();
            
            
            recipe.MapType = ...
                mapType;
            
            
            recipe.MapSource = ...
                mapSource;
            
            
            recipe.Band1 = ...
                [NaN NaN];
            
            
            recipe.Band2 = ...
                [NaN NaN];
            
            
            recipe.Normalization = ...
                "None";
            
            
            recipe.LocalBaseline = ...
                "None";
            
            
            recipe.CreatedAt = ...
                string(datetime( ...
                'now', ...
                'Format', ...
                'yyyy-MM-dd HH:mm:ss'));
            
            
            switch mapType
                
                
                % =========================================================
                % PEAK INTENSITY
                % =========================================================
                case "Peak intensity"
                    
                    answer = ...
                        inputdlg( ...
                        {'Target wavenumber (cm^-1):'}, ...
                        'Peak intensity map', ...
                        [1 48], ...
                        {'1003'});
                    
                    
                    if isempty(answer)
                        return
                    end
                    
                    
                    recipe.PeakWn = ...
                        str2double(answer{1});
                    
                    
                    if ~isfinite(recipe.PeakWn)
                        return
                    end
                    
                    
                    % =========================================================
                    % BAND TYPES
                    % =========================================================
                case { ...
                        "Integrated band", ...
                        "Baseline-corrected band area", ...
                        "Normalized band", ...
                        "Peak position", ...
                        "FWHM"}
                    
                    answer = ...
                        inputdlg( ...
                        { ...
                        'Band start (cm^-1):', ...
                        'Band end (cm^-1):'}, ...
                        char(mapType), ...
                        [1 48], ...
                        {'1440','1470'});
                    
                    
                    if isempty(answer)
                        return
                    end
                    
                    
                    vals = ...
                        cellfun( ...
                        @str2double, ...
                        answer);
                    
                    
                    if any(~isfinite(vals))
                        return
                    end
                    
                    
                    recipe.Band1 = ...
                        sort(vals(1:2));
                    
                    
                    if mapType == ...
                            "Baseline-corrected band area"
                        
                        recipe.LocalBaseline = ...
                            "Linear endpoints";
                        
                        
                    elseif mapType == ...
                            "Normalized band"
                        
                        recipe.Normalization = ...
                            "Total spectral area";
                        
                    end
                    
                    
                    % =========================================================
                    % SHARED RATIO / COMPOSITE SCORE
                    % =========================================================
                case { ...
                        "Ratio / composite score", ...
                        "Band ratio", ...
                        "Area ratio", ...
                        "Log ratio"}
                    
                    [ ...
                        ratioDef, ...
                        selectionMode, ...
                        manualBands, ...
                        ratioOK] = ...
                        app.chooseSpatialRatioRecipe();
                    
                    
                    if ~ratioOK
                        return
                    end
                    
                    
                    recipe.RatioSelectionMode = ...
                        selectionMode;
                    
                    
                    if selectionMode == ...
                            "Manual bands"
                        
                        recipe.RatioMode = ...
                            "ManualBands";
                        
                        
                        recipe.Band1 = ...
                            manualBands(1,:);
                        
                        
                        recipe.Band2 = ...
                            manualBands(2,:);
                        
                        
                        recipe.RatioRecipeID = ...
                            "";
                        
                        
                        recipe.RatioName = ...
                            sprintf( ...
                            '%.0f–%.0f / %.0f–%.0f', ...
                            recipe.Band1(1), ...
                            recipe.Band1(2), ...
                            recipe.Band2(1), ...
                            recipe.Band2(2));
                        
                        
                    else
                        
                        ratioDef = ...
                            app.normalizeRatioDefinitionRow( ...
                            ratioDef);
                        
                        
                        recipe.RatioMode = ...
                            "Definition";
                        
                        
                        recipe.RatioRecipeID = ...
                            ratioDef.RecipeID;
                        
                        
                        recipe.RatioName = ...
                            ratioDef.RatioName;
                        
                        
                        recipe.RatioFormula = ...
                            ratioDef.Formula;
                        
                        
                        recipe.RatioFeatureType = ...
                            ratioDef.FeatureType;
                        
                        
                        recipe.RatioDefinitionType = ...
                            ratioDef.DefinitionType;
                        
                        
                        recipe.RatioVariableMap = ...
                            ratioDef.VariableMap;
                        
                        
                        % -------------------------------------------------
                        % Snapshot tiny recipe for reproducibility.
                        %
                        % No matrix is duplicated.
                        % -------------------------------------------------
                        
                        recipe.RatioDefinition = ...
                            table2struct( ...
                            ratioDef);
                        
                    end
                    
                    
                    recipe.ApplyLog10 = ...
                        mapType == "Log ratio";
                    
                    
                    % =========================================================
                    % SIMILARITY
                    % =========================================================
                case { ...
                        "Pearson similarity", ...
                        "Cosine similarity", ...
                        "Spectral angle"}
                    
                    choices = { ...
                        'Mean spectrum of current scan', ...
                        'Reference pixel from current scan'};
                    
                    
                    [sel, accepted] = ...
                        listdlg( ...
                        'PromptString', ...
                        'Select similarity reference:', ...
                        'SelectionMode', ...
                        'single', ...
                        'ListString', ...
                        choices);
                    
                    
                    if ~accepted
                        return
                    end
                    
                    
                    if sel == 1
                        
                        recipe.ReferenceMode = ...
                            "Mean scan spectrum";
                        
                    else
                        
                        answer = ...
                            inputdlg( ...
                            { ...
                            'Reference pixel Y:', ...
                            'Reference pixel X:'}, ...
                            'Similarity reference', ...
                            [1 40], ...
                            {'1','1'});
                        
                        
                        if isempty(answer)
                            return
                        end
                        
                        
                        y = ...
                            round(str2double(answer{1}));
                        
                        x = ...
                            round(str2double(answer{2}));
                        
                        
                        if ~isfinite(y) || ...
                                ~isfinite(x)
                            
                            return
                        end
                        
                        
                        recipe.ReferenceMode = ...
                            "Reference pixel";
                        
                        
                        recipe.ReferencePixel = ...
                            [y x];
                        
                    end
                    
                    
                    % =========================================================
                    % PCA SCORE
                    % =========================================================
                case "PCA score"
                    
                    if isempty(app.pcaLoadings) || ...
                            app.pcaInputType ~= "Spectra"
                        
                        uialert( ...
                            app.UIFigure, ...
                            ['Run spectral PCA first.' newline ...
                            'Ratio PCA cannot be directly projected as a spectral PCA score map.'], ...
                            'PCA score map');
                        
                        return
                    end
                    
                    
                    maxPC = ...
                        size(app.pcaLoadings,2);
                    
                    
                    answer = ...
                        inputdlg( ...
                        {'PCA component number:'}, ...
                        'PCA score map', ...
                        [1 40], ...
                        {'1'});
                    
                    
                    if isempty(answer)
                        return
                    end
                    
                    
                    pc = ...
                        round(str2double(answer{1}));
                    
                    
                    if ~isfinite(pc) || ...
                            pc < 1 || ...
                            pc > maxPC
                        
                        return
                    end
                    
                    
                    recipe.PC = ...
                        pc;
                    
                    
                    % =========================================================
                    % CLASS MAP
                    % =========================================================
                case "Cluster / class map"
                    
                    if isempty(app.classModel)
                        
                        uialert( ...
                            app.UIFigure, ...
                            'Run classification first.', ...
                            'Class map');
                        
                        return
                    end
                    
                    
                    recipe.ModelType = ...
                        app.classModelType;
                    
                    
                otherwise
                    
                    return
                    
            end
            
            
            app.spatialCurrentRecipe = ...
                recipe;
            
            
            ok = true;
            
        end
        
        
        function closeDialogSafely(app, d)
            
            try
                if ~isempty(d) && isvalid(d)
                    delete(d);
                end
            catch
            end
            
        end


        function labels = buildSpatialPanelSelectionLabels(app)
            labels = strings(6,1);
            for k = 1:6
                datasetName = ...
                    string( ...
                    app.spatialPanels(k).DatasetName);
                if datasetName == ""
                    labels(k) = ...
                        sprintf( ...
                        'Panel %d — empty', ...
                        k);
                else
                    labels(k) = ...
                        sprintf( ...
                        'Panel %d — %s', ...
                        k, ...
                        datasetName);
                end
            end
        end


        function calculateSpatialPanelMap( ...
                app, ...
                panelNumber, ...
                recipe)
            
            panel = ...
                app.spatialPanels(panelNumber);
            
            
            if strlength(panel.DatasetID) == 0
                return
            end
            
            
            % =========================================================
            % 1. LOAD SOURCE DATA ON DEMAND
            % =========================================================
            [cubeData, wn] = ...
                app.loadSpatialDatasetCube( ...
                panel.DatasetID, ...
                recipe.MapSource);
            
            
            % =========================================================
            % 2. CALCULATE MAP
            % =========================================================
            [map2D, mapTitle, mapUnit] = ...
                app.calculateSpatialMapFromRecipe( ...
                cubeData, ...
                wn, ...
                recipe);
            
            
            % =========================================================
            % 3. STORE TEMPORARY MAP IN RAM ONLY
            % =========================================================
            app.spatialPanels(panelNumber).Recipe = ...
                recipe;
            
            app.spatialPanels(panelNumber).MapData = ...
                map2D;
            
            app.spatialPanels(panelNumber).MapTitle = ...
                mapTitle;
            
            app.spatialPanels(panelNumber).MapUnit = ...
                mapUnit;
            
            app.spatialPanels(panelNumber).Calculated = ...
                true;
            
            app.spatialPanels(panelNumber).CalculatedAt = ...
                string(datetime( ...
                'now', ...
                'Format', ...
                'yyyy-MM-dd HH:mm:ss'));
            
            
            % =========================================================
            % 4. PLOT
            % =========================================================
            axesList = ...
                app.getSpatialAxes();
            
            ax = ...
                axesList{panelNumber};
            
            
            app.plotSpatialChemicalMap( ...
                ax, ...
                map2D, ...
                panel.DatasetName, ...
                mapTitle);
        end
        
        
        function Tout = getCanonicalPeakLibrary(app)
            
            % =============================================================
            % GETCANONICALPEAKLIBRARY
            %
            % Converts the current Single-mode peak library to one common
            % internal schema used by:
            %
            %   Ratio recipes
            %   Composite scores
            %   PCA ratio
            %   Spatial ratio / score maps
            %
            % Canonical columns:
            %
            %   ExperimentalPeak
            %   LowWN
            %   HighWN
            %   CenterWN
            %   Interpretation
            %   Source
            %
            % IMPORTANT:
            % This does NOT overwrite app.peakLibrary.
            % It only returns a normalized lightweight table.
            % =============================================================
            
            Tout = table();
            
            
            % =============================================================
            % 1. VALIDATE SOURCE
            % =============================================================
            
            Tin = ...
                app.peakLibrary;
            
            
            if isempty(Tin) || ...
                    ~istable(Tin) || ...
                    height(Tin) == 0
                
                return
            end
            
            
            vars = ...
                string(Tin.Properties.VariableNames);
            
            
            % =============================================================
            % 2. ALREADY CANONICAL
            % =============================================================
            
            required = [ ...
                "LowWN", ...
                "HighWN", ...
                "CenterWN", ...
                "Interpretation"];
            
            
            if all(ismember(required,vars))
                
                Tout = ...
                    Tin;
                
                
                if ~ismember( ...
                        "ExperimentalPeak", ...
                        vars)
                    
                    Tout.ExperimentalPeak = ...
                        compose( ...
                        "%.1f", ...
                        double(Tout.CenterWN));
                    
                    
                    Tout = ...
                        movevars( ...
                        Tout, ...
                        "ExperimentalPeak", ...
                        "Before", ...
                        1);
                    
                end
                
                
                if ~ismember( ...
                        "Source", ...
                        string(Tout.Properties.VariableNames))
                    
                    Tout.Source = ...
                        repmat( ...
                        "Defined library", ...
                        height(Tout), ...
                        1);
                    
                end
                
                
                % Keep canonical columns only.
                Tout = ...
                    Tout(:,{ ...
                    'ExperimentalPeak', ...
                    'LowWN', ...
                    'HighWN', ...
                    'CenterWN', ...
                    'Interpretation', ...
                    'Source'});
                
                
                return
            end
            
            
            % =============================================================
            % 3. IDENTIFY ORIGINAL LIBRARY COLUMNS
            %
            % sg_define_spec / imported library may contain columns such as:
            %
            % Theoretical peak
            % Interpretation
            % <project name>
            %
            % rather than LowWN/HighWN/CenterWN.
            % =============================================================
            
            varsLower = ...
                lower(strtrim(vars));
            
            
            theoryCol = ...
                find( ...
                contains(varsLower,"theoretical"), ...
                1, ...
                'first');
            
            
            if isempty(theoryCol)
                
                theoryCol = ...
                    find( ...
                    contains(varsLower,"peak") & ...
                    ~contains(varsLower,"interpret"), ...
                    1, ...
                    'first');
                
            end
            
            
            interpretationCol = ...
                find( ...
                contains(varsLower,"interpretation") | ...
                contains(varsLower,"meaning") | ...
                contains(varsLower,"assignment"), ...
                1, ...
                'first');
            
            
            if isempty(theoryCol)
                
                error( ...
                    ['Peak library cannot be converted to the shared recipe format.' ...
                    newline newline ...
                    'No theoretical/peak column was found.' ...
                    newline ...
                    'Current columns: ' ...
                    strjoin(vars,", ")]);
                
            end
            
            
            % =============================================================
            % 4. OPTIONAL CURRENT-PROJECT OBSERVED PEAK COLUMN
            % =============================================================
            
            projectCol = [];
            
            
            projectName = ...
                string(app.projectName);
            
            
            if strlength(strtrim(projectName)) > 0
                
                projectCol = ...
                    find( ...
                    strcmpi( ...
                    strtrim(vars), ...
                    strtrim(projectName)), ...
                    1, ...
                    'first');
                
            end
            
            
            % =============================================================
            % 5. PREALLOCATE
            % =============================================================
            
            n = ...
                height(Tin);
            
            
            ExperimentalPeak = ...
                strings(n,1);
            
            
            LowWN = ...
                nan(n,1);
            
            
            HighWN = ...
                nan(n,1);
            
            
            CenterWN = ...
                nan(n,1);
            
            
            Interpretation = ...
                strings(n,1);
            
            
            Source = ...
                repmat( ...
                "Defined library", ...
                n, ...
                1);
            
            
            % =============================================================
            % 6. PARSE INTERPRETATION
            % =============================================================
            
            if ~isempty(interpretationCol)
                
                for k = 1:n
                    
                    try
                        
                        Interpretation(k) = ...
                            string( ...
                            Tin{k,interpretationCol});
                        
                    catch
                        
                        Interpretation(k) = "";
                        
                    end
                    
                end
                
            else
                
                Interpretation(:) = ...
                    "";
                
            end
            
            
            % =============================================================
            % 7. PARSE THEORETICAL PEAK / RANGE
            % =============================================================
            
            try
                
                theoryText = ...
                    string( ...
                    Tin{:,theoryCol});
                
            catch
                
                theoryText = ...
                    string( ...
                    Tin.( ...
                    Tin.Properties.VariableNames{theoryCol}));
                
            end
            
            
            % Spectral bounds for clamping.
            wnAxis = ...
                double(app.analysisWn(:));
            
            
            if isempty(wnAxis)
                
                wnAxis = ...
                    double(app.wn(:));
                
            end
            
            
            if isempty(wnAxis)
                
                wnMinimum = ...
                    -inf;
                
                wnMaximum = ...
                    inf;
                
            else
                
                wnMinimum = ...
                    min(wnAxis,[],'omitnan');
                
                wnMaximum = ...
                    max(wnAxis,[],'omitnan');
                
            end
            
            
            for k = 1:n
                
                txt = ...
                    strtrim( ...
                    theoryText(k));
                
                
                nums = ...
                    regexp( ...
                    char(txt), ...
                    '[-+]?\d*\.?\d+', ...
                    'match');
                
                
                if isempty(nums)
                    continue
                end
                
                
                nums = ...
                    str2double(nums);
                
                
                nums = ...
                    nums(isfinite(nums));
                
                
                if isempty(nums)
                    continue
                end
                
                
                % ---------------------------------------------------------
                % Theoretical range
                % ---------------------------------------------------------
                
                if numel(nums) == 1
                    
                    theoryCenter = ...
                        nums(1);
                    
                    
                    theoryLow = ...
                        theoryCenter - 5;
                    
                    
                    theoryHigh = ...
                        theoryCenter + 5;
                    
                else
                    
                    theoryLow = ...
                        min(nums(1:2));
                    
                    
                    theoryHigh = ...
                        max(nums(1:2));
                    
                    
                    theoryCenter = ...
                        mean( ...
                        [theoryLow theoryHigh]);
                    
                end
                
                
                % ---------------------------------------------------------
                % Project-specific observed center if available
                % ---------------------------------------------------------
                
                observedCenter = ...
                    NaN;
                
                
                if ~isempty(projectCol)
                    
                    try
                        
                        observedText = ...
                            string( ...
                            Tin{k,projectCol});
                        
                        
                        obsNums = ...
                            regexp( ...
                            char(observedText), ...
                            '[-+]?\d*\.?\d+', ...
                            'match');
                        
                        
                        if ~isempty(obsNums)
                            
                            observedCenter = ...
                                str2double( ...
                                obsNums{1});
                            
                        end
                        
                    catch
                    end
                    
                end
                
                
                % ---------------------------------------------------------
                % Use observed center when available
                % ---------------------------------------------------------
                
                if isfinite(observedCenter)
                    
                    center = ...
                        observedCenter;
                    
                    
                    halfWidth = ...
                        max( ...
                        (theoryHigh-theoryLow)/2, ...
                        5);
                    
                    
                    low = ...
                        center-halfWidth;
                    
                    
                    high = ...
                        center+halfWidth;
                    
                else
                    
                    center = ...
                        theoryCenter;
                    
                    
                    low = ...
                        theoryLow;
                    
                    
                    high = ...
                        theoryHigh;
                    
                end
                
                
                % ---------------------------------------------------------
                % Clamp to current spectral axis
                % ---------------------------------------------------------
                
                low = ...
                    max( ...
                    wnMinimum, ...
                    low);
                
                
                high = ...
                    min( ...
                    wnMaximum, ...
                    high);
                
                
                if ~isfinite(low) || ...
                        ~isfinite(high) || ...
                        ~isfinite(center) || ...
                        low > high
                    
                    continue
                end
                
                
                ExperimentalPeak(k) = ...
                    txt;
                
                
                LowWN(k) = ...
                    low;
                
                
                HighWN(k) = ...
                    high;
                
                
                CenterWN(k) = ...
                    center;
                
            end
            
            
            % =============================================================
            % 8. KEEP VALID PEAKS
            % =============================================================
            
            keep = ...
                isfinite(LowWN) & ...
                isfinite(HighWN) & ...
                isfinite(CenterWN);
            
            
            ExperimentalPeak = ...
                ExperimentalPeak(keep);
            
            
            LowWN = ...
                LowWN(keep);
            
            
            HighWN = ...
                HighWN(keep);
            
            
            CenterWN = ...
                CenterWN(keep);
            
            
            Interpretation = ...
                Interpretation(keep);
            
            
            Source = ...
                Source(keep);
            
            
            % =============================================================
            % 9. OUTPUT CANONICAL TABLE
            % =============================================================
            
            Tout = ...
                table( ...
                ExperimentalPeak, ...
                LowWN, ...
                HighWN, ...
                CenterWN, ...
                Interpretation, ...
                Source, ...
                'VariableNames', { ...
                'ExperimentalPeak', ...
                'LowWN', ...
                'HighWN', ...
                'CenterWN', ...
                'Interpretation', ...
                'Source'});
            
            
            if isempty(Tout)
                
                error( ...
                    ['Peak library was found, but no valid peak could be ' ...
                    'converted to LowWN/HighWN/CenterWN metadata.']);
                
            end
            
        end
        
        

        function plotSpatialOptimizedOverlay( ...
                app, ...
                panelNumber, ...
                rule)
            
            % =============================================================
            % PLOTSPATIALOPTIMIZEDOVERLAY
            %
            % DISPLAY ARCHITECTURE
            %
            %   FULL TRACE / REFERENCE IMAGE
            %               +
            %   COLORED FEATURE OVERLAY
            %
            % AnalysisMask is NOT displayed.
            %
            % AnalysisMask is used only to restrict:
            %   1. which pixels enter percentile calculation;
            %   2. which pixels may become colored feature overlay.
            %
            % Therefore:
            %
            %   Top 20% =
            %       top 20% INSIDE the valid biological analysis domain
            %
            % not:
            %
            %       top 20% of the entire image/background frame.
            % =============================================================
            
            
            % =============================================================
            % 1. VALIDATE PANEL
            % =============================================================
            if isempty(app.spatialPanels) || ...
                    panelNumber < 1 || ...
                    panelNumber > numel(app.spatialPanels)
                
                error( ...
                    'Invalid Spatial Distribution panel.');
                
            end
            
            
            P = ...
                app.spatialPanels(panelNumber);
            
            
            if ~P.Calculated || ...
                    isempty(P.MapData)
                
                error( ...
                    'This spatial panel does not contain a calculated map.');
                
            end
            
            
            map2D = ...
                double(P.MapData);
            
            
            if ndims(map2D) ~= 2
                
                error( ...
                    'Spatial feature map must be two-dimensional.');
                
            end
            
            
            % =============================================================
            % 2. BUILD FULL TRACE / REFERENCE BACKGROUND
            %
            % IMPORTANT:
            % This is what remains visible everywhere.
            %
            % The biological mask is NOT drawn here.
            % =============================================================
            backgroundMap = ...
                double(P.ReferenceData);
            
            
            % Safety fallback only.
            if isempty(backgroundMap) || ...
                    ~isequal(size(backgroundMap),size(map2D))
                
                backgroundMap = ...
                    map2D;
                
            end
            
            
            backgroundValues = ...
                backgroundMap(isfinite(backgroundMap));
            
            
            if isempty(backgroundValues)
                
                bgNorm = ...
                    zeros(size(backgroundMap));
                
            else
                
                bgLow = ...
                    prctile( ...
                    backgroundValues, ...
                    2);
                
                bgHigh = ...
                    prctile( ...
                    backgroundValues, ...
                    98);
                
                
                if ~isfinite(bgLow) || ...
                        ~isfinite(bgHigh) || ...
                        bgHigh <= bgLow
                    
                    bgLow = ...
                        min(backgroundValues);
                    
                    bgHigh = ...
                        max(backgroundValues);
                    
                end
                
                
                if ~isfinite(bgLow) || ...
                        ~isfinite(bgHigh) || ...
                        bgHigh <= bgLow
                    
                    bgNorm = ...
                        zeros(size(backgroundMap));
                    
                else
                    
                    bgNorm = ...
                        (backgroundMap - bgLow) ./ ...
                        (bgHigh - bgLow);
                    
                end
                
            end
            
            
            bgNorm(~isfinite(bgNorm)) = ...
                0;
            
            
            bgNorm = ...
                max( ...
                0, ...
                min( ...
                1, ...
                bgNorm));
            
            
            grayRGB = ...
                repmat( ...
                bgNorm, ...
                1, ...
                1, ...
                3);
            
            
            % =============================================================
            % 3. RESOLVE ANALYSIS DOMAIN
            %
            % Mask hierarchy is handled by resolveSpatialAnalysisMask:
            %
            %   ValidPixelMask2D
            %       AND
            %   CellMask2D
            %       AND NOT
            %   UncertainMask2D
            %
            % If CellMask is absent but BackgroundMask exists:
            %   NOT BackgroundMask2D
            %
            % THIS MASK IS NEVER PLOTTED.
            % =============================================================
            [analysisMask, maskInfo] = ...
                app.getSpatialPanelAnalysisMask( ...
                panelNumber, ...
                size(map2D));
            
            
            if isempty(analysisMask) || ...
                    ~isequal(size(analysisMask),size(map2D))
                
                analysisMask = ...
                    true(size(map2D));
                
                maskInfo.Source = ...
                    "Fallback: complete spatial frame";
                
            end
            
            
            analysisMask = ...
                logical(analysisMask);
            
            
            % =============================================================
            % 4. VALID FEATURE DOMAIN
            %
            % Only these pixels may:
            %   - enter percentile statistics
            %   - become colored overlay
            %
            % Again: analysisMask itself is NOT drawn.
            % =============================================================
            validDomain = ...
                analysisMask & ...
                isfinite(map2D);
            
            
            featureValues = ...
                map2D(validDomain);
            
            
            if isempty(featureValues)
                
                error( ...
                    ['No finite feature values remain inside the spatial ' ...
                    'analysis mask.']);
                
            end
            
            
            % =============================================================
            % 5. RESOLVE FEATURE THRESHOLDS
            %
            % Percentiles are calculated ONLY from featureValues,
            % i.e. pixels inside AnalysisMask.
            % =============================================================
            percentileLow = ...
                NaN;
            
            percentileHigh = ...
                NaN;
            
            valueLow = ...
                NaN;
            
            valueHigh = ...
                NaN;
            
            
            switch string(rule.Type)
                
                
                % =========================================================
                % PERCENTILE
                % =========================================================
                case "Percentile"
                    
                    percentileLow = ...
                        double(rule.PercentileLow);
                    
                    percentileHigh = ...
                        double(rule.PercentileHigh);
                    
                    
                    if percentileLow <= 0
                        
                        valueLow = ...
                            -inf;
                        
                    else
                        
                        valueLow = ...
                            prctile( ...
                            featureValues, ...
                            percentileLow);
                        
                    end
                    
                    
                    if percentileHigh >= 100
                        
                        valueHigh = ...
                            inf;
                        
                    else
                        
                        valueHigh = ...
                            prctile( ...
                            featureValues, ...
                            percentileHigh);
                        
                    end
                    
                    
                    % =========================================================
                    % ABSOLUTE VALUE
                    % =========================================================
                case "Absolute"
                    
                    valueLow = ...
                        double(rule.AbsoluteLow);
                    
                    valueHigh = ...
                        double(rule.AbsoluteHigh);
                    
                    
                otherwise
                    
                    error( ...
                        'Unsupported optimize rule type: %s', ...
                        string(rule.Type));
                    
            end
            
            
            % =============================================================
            % 6. FEATURE OVERLAY DOMAIN
            %
            % THIS is the key correction.
            %
            % Old:
            %
            %   isfinite(map2D) & value condition
            %
            % New:
            %
            %   AnalysisMask
            %       AND
            %   finite feature
            %       AND
            %   requested feature range
            %
            % Mask is therefore only a gate.
            % =============================================================
            overlayMask = ...
                validDomain & ...
                map2D >= valueLow & ...
                map2D <= valueHigh;
            
            
            % =============================================================
            % 7. GET TARGET AXIS
            % =============================================================
            axesList = ...
                app.getSpatialAxes();
            
            
            ax = ...
                axesList{panelNumber};
            
            
            cla( ...
                ax, ...
                'reset');
            
            
            % =============================================================
            % 8. DRAW COMPLETE TRACE BACKGROUND
            %
            % Full trace remains visible.
            % No CellMask / ValidMask contour.
            % No black mask.
            % No mask image.
            % =============================================================
            image( ...
                ax, ...
                grayRGB);
            
            
            axis( ...
                ax, ...
                'image');
            
            
            axis( ...
                ax, ...
                'tight');
            
            
            ax.YDir = ...
                'normal';
            
            
            ax.Box = ...
                'on';
            
            
            hold( ...
                ax, ...
                'on');
            
            
            % =============================================================
            % 9. DRAW COLORED FEATURE LABEL / OVERLAY
            %
            % The numerical feature map is drawn, but AlphaData permits
            % visibility ONLY for overlayMask.
            %
            % Therefore pixels outside the biological analysis domain simply
            % show the underlying trace.
            % =============================================================
            hMap = ...
                imagesc( ...
                ax, ...
                map2D);
            
            
            try
                
                hMap.Interpolation = ...
                    'nearest';
                
            catch
            end
            
            
            overlayOpacity = ...
                0.72;
            
            
            hMap.AlphaData = ...
                overlayOpacity .* ...
                double(overlayMask);
            
            
            hold( ...
                ax, ...
                'off');
            
            
            % =============================================================
            % 10. FEATURE COLORMAP
            % =============================================================
            app.applySpatialColormap(ax);
            
            
            % =============================================================
            % 11. FEATURE COLOR LIMITS
            %
            % Do not let background pixels control feature color scaling.
            % Use only the biological/valid feature domain.
            % =============================================================
            colorValues = ...
                featureValues;
            
            
            if ~isempty(colorValues)
                
                cLow = ...
                    prctile( ...
                    colorValues, ...
                    1);
                
                cHigh = ...
                    prctile( ...
                    colorValues, ...
                    99);
                
                
                if ~isfinite(cLow) || ...
                        ~isfinite(cHigh) || ...
                        cHigh <= cLow
                    
                    cLow = ...
                        min(colorValues);
                    
                    cHigh = ...
                        max(colorValues);
                    
                end
                
                
                if isfinite(cLow) && ...
                        isfinite(cHigh) && ...
                        cHigh > cLow
                    
                    clim( ...
                        ax, ...
                        [cLow cHigh]);
                    
                end
                
            end
            
            
            colorbar(ax);
            
            
            % =============================================================
            % 12. TITLES / LABELS
            % =============================================================
            title( ...
                ax, ...
                { ...
                char(P.DatasetName); ...
                char( ...
                string(P.MapTitle) + ...
                " | " + ...
                string(rule.SelectionMode))}, ...
                'Interpreter', ...
                'none');
            
            
            xlabel( ...
                ax, ...
                'X pixel');
            
            
            ylabel( ...
                ax, ...
                'Y pixel');
            
            
            grid( ...
                ax, ...
                'off');
            
            
            % =============================================================
            % 13. SAVE ONLY LIGHTWEIGHT OVERLAY PROVENANCE
            %
            % No trace/map/mask matrix is copied into history.
            % =============================================================
            try
                
                app.ensureSpatialOptimizeHistorySchema();
                
                
                historyEntry = struct();
                
                historyEntry.OverlayID = ...
                    "SPATIAL_OVERLAY_" + ...
                    string(datetime( ...
                    'now', ...
                    'Format', ...
                    'yyyyMMdd_HHmmss_SSS'));
                
                
                historyEntry.PanelID = ...
                    panelNumber;
                
                
                historyEntry.DatasetID = ...
                    string(P.DatasetID);
                
                
                historyEntry.DatasetName = ...
                    string(P.DatasetName);
                
                
                if isfield(P.Recipe,'MapType')
                    
                    historyEntry.MapType = ...
                        string(P.Recipe.MapType);
                    
                else
                    
                    historyEntry.MapType = ...
                        string(P.MapTitle);
                    
                end
                
                
                historyEntry.SelectionMode = ...
                    string(rule.SelectionMode);
                
                
                historyEntry.PercentileLow = ...
                    percentileLow;
                
                
                historyEntry.PercentileHigh = ...
                    percentileHigh;
                
                
                historyEntry.ValueThresholdLow = ...
                    valueLow;
                
                
                historyEntry.ValueThresholdHigh = ...
                    valueHigh;
                
                
                historyEntry.Opacity = ...
                    overlayOpacity;
                
                
                historyEntry.CreatedAt = ...
                    string(datetime( ...
                    'now', ...
                    'Format', ...
                    'yyyy-MM-dd HH:mm:ss'));
                
                
                historyEntry.User = ...
                    app.homeUser;
                
                
                app.spatialOptimizeHistory(end+1) = ...
                    historyEntry;
                
            catch ME
                
                warning( ...
                    'RamanCube:SpatialOptimizeHistory', ...
                    'Could not update spatial optimize history: %s', ...
                    ME.message);
                
            end
            
            
            % =============================================================
            % 14. AUDIT
            %
            % Mask provenance is logged as text only.
            % The mask matrix itself is not saved here.
            % =============================================================
            try
                
                app.logActivity( ...
                    "Spatial distribution", ...
                    "Optimize scan", ...
                    string(rule.SelectionMode), ...
                    "DISPLAYED", ...
                    "Panel " + ...
                    string(panelNumber) + ...
                    " | Dataset: " + ...
                    string(P.DatasetName) + ...
                    " | Analysis domain: " + ...
                    string(maskInfo.Source) + ...
                    " | Eligible pixels: " + ...
                    string(nnz(validDomain)) + ...
                    " | Displayed feature pixels: " + ...
                    string(nnz(overlayMask)));
                
            catch
            end
            
        end
        
        function saveAnalysisMetadata(app, showMessage)
            
            if nargin < 2
                showMessage = true;
            end
            
            
            % =========================================================
            % 1. ENSURE SESSION FOLDER
            % =========================================================
            
            if strlength(app.singleOutputSessionFolder) == 0 || ...
                    ~isfolder(app.singleOutputSessionFolder)
                
                app.createSingleOutputSessionFolder();
                
            end
            
            
            metadataFolder = ...
                fullfile( ...
                app.singleOutputSessionFolder, ...
                '06_Analysis_Metadata');
            
            
            if ~isfolder(metadataFolder)
                
                mkdir(metadataFolder);
                
            end
            
            
            % =========================================================
            % 2. BUILD SMALL METADATA STRUCT
            %
            % EXCLUDED ON PURPOSE:
            %
            % spectra
            % rawSpectra
            % analysisSpectra
            % analysisRawSpectra
            % cubes
            % ratioScreeningValues
            % pcaX
            % pcaScores
            % pcaLoadings
            % classX
            % classScores
            % spatial MapData
            % spatial ReferenceData
            % spatialCache
            % classification model
            % =========================================================
            
            M = struct();
            
            
            M.SchemaVersion = ...
                app.analysisMetadataVersion;
            
            
            M.SavedAt = ...
                string(datetime( ...
                'now', ...
                'Format', ...
                'yyyy-MM-dd HH:mm:ss'));
            
            
            % =========================================================
            % SESSION / SOURCE PROVENANCE
            % =========================================================
            
            M.Session = struct();
            
            M.Session.ID = ...
                app.singleSessionID;
            
            M.Session.Name = ...
                app.singleSessionName;
            
            M.Session.User = ...
                app.homeUser;
            
            M.Session.InputFolder = ...
                app.singleInputFolder;
            
            M.Session.OutputFolder = ...
                app.singleOutputSessionFolder;
            
            M.Session.AnalysisSource = ...
                app.analysisSource;
            
            M.Session.AnalysisHistory = ...
                app.analysisHistory;
            
            
            % =========================================================
            % PEAK / RANGE METADATA
            % =========================================================
            
            M.PeakLibrary = ...
                app.peakLibrary;
            
            
            if ~isempty(app.spectralDef)
                
                compactSpectralDef = ...
                    struct();
                
                
                if isfield(app.spectralDef,'roiRanges')
                    
                    compactSpectralDef.roiRanges = ...
                        app.spectralDef.roiRanges;
                    
                else
                    
                    compactSpectralDef.roiRanges = ...
                        [];
                    
                end
                
                
                M.SpectralDefinition = ...
                    compactSpectralDef;
                
            else
                
                M.SpectralDefinition = ...
                    struct('roiRanges',[]);
                
            end
            
            
            % =========================================================
            % GROUP DEFINITIONS
            % =========================================================
            
            M.Groups = ...
                app.compareGroups;
            
            
            % =========================================================
            % RATIO NOTES
            % =========================================================
            
            app.ensureRatioDefinitionSchema();
            
            
            M.RatioNotes = ...
                app.ratioDefinitions;
            
            
            % =========================================================
            % PCA RECIPE
            % =========================================================
            
            M.PCA = struct();
            
            M.PCA.LastRunRecipe = ...
                app.pcaRunRecipe;
            
            M.PCA.InputType = ...
                app.pcaInputType;
            
            M.PCA.Normalization = ...
                app.pcaNormMethod;
            
            M.PCA.VariableNames = ...
                app.pcaVariableNames;
            
            
            % =========================================================
            % CLASSIFICATION RECIPE
            % =========================================================
            
            M.Classification = struct();
            
            M.Classification.LastRunRecipe = ...
                app.classRunRecipe;
            
            M.Classification.Method = ...
                app.classMethod;
            
            M.Classification.Normalization = ...
                app.classNormMethod;
            
            M.Classification.GroupNames = ...
                app.classGroupNames;
            
            M.Classification.AccuracyPercent = ...
                app.classAccuracy;
            
            
            % =========================================================
            % SPATIAL
            % =========================================================
            
            M.Spatial = struct();
            
            M.Spatial.DatasetRegistry = ...
                app.spatialDatasets;
            
            M.Spatial.RecipeHistory = ...
                app.spatialRecipeHistory;
            
            M.Spatial.OptimizeHistory = ...
                app.spatialOptimizeHistory;
            
            M.Spatial.ReferenceBand = ...
                app.spatialReferenceBand;
            
            
            % =========================================================
            % 3. ATOMIC SAVE
            %
            % Fixed file name:
            % no timestamp accumulation / no overlapping copies.
            % =========================================================
            
            finalFile = ...
                fullfile( ...
                metadataFolder, ...
                'analysis_metadata.mat');


            % =============================================================
            % INTEGRATED INTERPRETATION REGISTRY
            %
            % IMPORTANT:
            % - metadata only
            % - no spectra
            % - no ratio value matrix
            % - no PCA scores/loadings
            % - no duplicated Raman data
            % =============================================================
            
            app.ensureInterpretationRegistrySchema();
            
            M.Interpretation = struct();
            
            M.Interpretation.SchemaVersion = ...
                app.interpretationMetadataVersion;
            
            M.Interpretation.Registry = ...
                app.interpretationRegistry;
            
            M.Interpretation.EntryCount = ...
                height(app.interpretationRegistry);
            
            M.Interpretation.SavedAt = ...
                string(datetime( ...
                'now', ...
                'Format', ...
                'yyyy-MM-dd HH:mm:ss'));


            tempFile = ...
                fullfile( ...
                metadataFolder, ...
                'analysis_metadata_tmp.mat');
            
            
            save( ...
                tempFile, ...
                'M');
            
            
            if isfile(finalFile)
                
                delete(finalFile);
                
            end
            
            
            movefile( ...
                tempFile, ...
                finalFile);
            
            
            % =========================================================
            % 4. HUMAN-READABLE RATIO NOTE TABLE
            %
            % Also overwritten; never accumulated.
            % =========================================================
            
            ratioFile = ...
                fullfile( ...
                metadataFolder, ...
                'ratio_notes.tsv');
            
            
            if ~isempty(app.ratioDefinitions)
                
                writetable( ...
                    app.ratioDefinitions, ...
                    ratioFile, ...
                    'FileType', ...
                    'text', ...
                    'Delimiter', ...
                    '\t');
                
            elseif isfile(ratioFile)
                
                delete(ratioFile);
                
            end
            
            % =============================================================
            % HUMAN-READABLE INTERPRETATION REGISTRY
            %
            % This is a small metadata table for audit/review.
            % It is overwritten on every metadata save.
            % =============================================================
            
            interpretationFile = ...
                fullfile( ...
                metadataFolder, ...
                'interpretation_registry.tsv');
            
            app.ensureInterpretationRegistrySchema();
            
            if ~isempty(app.interpretationRegistry)
                
                writetable( ...
                    app.interpretationRegistry, ...
                    interpretationFile, ...
                    'FileType', ...
                    'text', ...
                    'Delimiter', ...
                    '\t');
                
            elseif isfile(interpretationFile)
                
                delete(interpretationFile);
                
            end
            
            
            % =========================================================
            % 5. MANIFEST
            % =========================================================
            
            manifestFile = ...
                fullfile( ...
                metadataFolder, ...
                'metadata_manifest.txt');
            
            
            fid = ...
                fopen( ...
                manifestFile, ...
                'w');
            
            
            if fid ~= -1
                
                cleaner = ...
                    onCleanup(@() fclose(fid));
                
                
                fprintf( ...
                    fid, ...
                    'RAMANCUBE ANALYSIS METADATA\n');
                
                fprintf( ...
                    fid, ...
                    '===========================\n\n');
                
                
                fprintf( ...
                    fid, ...
                    'Schema: %s\n', ...
                    char(M.SchemaVersion));
                
                
                fprintf( ...
                    fid, ...
                    'Saved: %s\n', ...
                    char(M.SavedAt));
                
                
                fprintf( ...
                    fid, ...
                    'Session ID: %s\n', ...
                    char(app.singleSessionID));
                
                
                fprintf( ...
                    fid, ...
                    'Input source: %s\n', ...
                    char(app.singleInputFolder));
                
                
                fprintf( ...
                    fid, ...
                    'Ratio notes: %d\n', ...
                    height(app.ratioDefinitions));
                
                fprintf( ...
                    fid, ...
                    'Committed interpretations: %d\n', ...
                    height(app.interpretationRegistry));
                
                
                fprintf( ...
                    fid, ...
                    'Spatial recipes: %d\n', ...
                    numel(app.spatialRecipeHistory));
                
                
                fprintf( ...
                    fid, ...
                    'Optimize overlays: %d\n', ...
                    numel(app.spatialOptimizeHistory));
                
                
                fprintf( ...
                    fid, ...
                    '\nLarge spectral/cube matrices duplicated: NO\n');
                
                fprintf( ...
                    fid, ...
                    'Interpretation registry stores metadata only: YES\n');
                
                
                fprintf( ...
                    fid, ...
                    'Analysis metadata file: analysis_metadata.mat\n');
                
            end
            
            
            if showMessage
                
                msgbox( ...
                    sprintf( ...
                    ['Analysis metadata saved.\n\n' ...
                    'No Raman spectra, ratio matrices, maps, or cubes were duplicated.\n\n' ...
                    'Folder:\n%s'], ...
                    metadataFolder), ...
                    'Metadata backup');
                
            end
            
        end


        function [rule, ok] = ...
                promptSpatialOptimizeRule(app)
            
            %#ok<INUSD>
            
            rule = struct();
            
            ok = false;
            
            
            modes = { ...
                'Top 10%', ...
                'Top 20%', ...
                'Top 30%', ...
                'Top 40%', ...
                'Top 50%', ...
                'Top 60%', ...
                'Top 70%', ...
                'Top 80%', ...
                'Top 90%', ...
                'All', ...
                'High', ...
                'Medium', ...
                'Low', ...
                'High + Medium', ...
                'Custom percentile range...', ...
                'Custom absolute value range...'};
            
            
            [sel, accepted] = ...
                listdlg( ...
                'PromptString', ...
                'Select colored overlay range:', ...
                'SelectionMode', ...
                'single', ...
                'ListString', ...
                modes, ...
                'InitialValue', ...
                1, ...
                'ListSize', ...
                [390 360]);
            
            
            if ~accepted
                return
            end
            
            
            selectionMode = ...
                string(modes{sel});
            
            
            rule.SelectionMode = ...
                selectionMode;
            
            
            rule.Type = ...
                "Percentile";
            
            
            rule.PercentileLow = ...
                NaN;
            
            
            rule.PercentileHigh = ...
                NaN;
            
            
            rule.AbsoluteLow = ...
                NaN;
            
            
            rule.AbsoluteHigh = ...
                NaN;
            
            
            switch selectionMode
                
                
                % =========================================================
                % TOP X %
                % =========================================================
                case "Top 10%"
                    
                    rule.PercentileLow = 90;
                    rule.PercentileHigh = 100;
                    
                    
                case "Top 20%"
                    
                    rule.PercentileLow = 80;
                    rule.PercentileHigh = 100;
                    
                    
                case "Top 30%"
                    
                    rule.PercentileLow = 70;
                    rule.PercentileHigh = 100;
                    
                    
                case "Top 40%"
                    
                    rule.PercentileLow = 60;
                    rule.PercentileHigh = 100;
                    
                    
                case "Top 50%"
                    
                    rule.PercentileLow = 50;
                    rule.PercentileHigh = 100;
                    
                    
                case "Top 60%"
                    
                    rule.PercentileLow = 40;
                    rule.PercentileHigh = 100;
                    
                    
                case "Top 70%"
                    
                    rule.PercentileLow = 30;
                    rule.PercentileHigh = 100;
                    
                    
                case "Top 80%"
                    
                    rule.PercentileLow = 20;
                    rule.PercentileHigh = 100;
                    
                    
                case "Top 90%"
                    
                    rule.PercentileLow = 10;
                    rule.PercentileHigh = 100;
                    
                    
                case "All"
                    
                    rule.PercentileLow = 0;
                    rule.PercentileHigh = 100;
                    
                    
                    % =========================================================
                    % LEVEL SHORTCUTS
                    % =========================================================
                case "High"
                    
                    rule.PercentileLow = 80;
                    rule.PercentileHigh = 100;
                    
                    
                case "Medium"
                    
                    rule.PercentileLow = 40;
                    rule.PercentileHigh = 80;
                    
                    
                case "Low"
                    
                    rule.PercentileLow = 0;
                    rule.PercentileHigh = 40;
                    
                    
                case "High + Medium"
                    
                    rule.PercentileLow = 40;
                    rule.PercentileHigh = 100;
                    
                    
                    % =========================================================
                    % CUSTOM PERCENTILE
                    % =========================================================
                case "Custom percentile range..."
                    
                    answer = ...
                        inputdlg( ...
                        { ...
                        'Lower percentile (0–100):', ...
                        'Upper percentile (0–100):'}, ...
                        'Custom percentile overlay', ...
                        [1 45], ...
                        {'70','100'});
                    
                    
                    if isempty(answer)
                        return
                    end
                    
                    
                    p1 = ...
                        str2double(answer{1});
                    
                    p2 = ...
                        str2double(answer{2});
                    
                    
                    if any(~isfinite([p1 p2])) || ...
                            p1 < 0 || ...
                            p2 > 100 || ...
                            p1 >= p2
                        
                        uialert( ...
                            app.UIFigure, ...
                            'Percentile range must satisfy 0 <= low < high <= 100.', ...
                            'Optimize scan');
                        
                        return
                    end
                    
                    
                    rule.PercentileLow = ...
                        p1;
                    
                    
                    rule.PercentileHigh = ...
                        p2;
                    
                    
                    % =========================================================
                    % CUSTOM ABSOLUTE VALUE
                    % =========================================================
                case "Custom absolute value range..."
                    
                    answer = ...
                        inputdlg( ...
                        { ...
                        'Minimum map value:', ...
                        'Maximum map value:'}, ...
                        'Absolute overlay range', ...
                        [1 45], ...
                        {'0','1'});
                    
                    
                    if isempty(answer)
                        return
                    end
                    
                    
                    v1 = ...
                        str2double(answer{1});
                    
                    v2 = ...
                        str2double(answer{2});
                    
                    
                    if any(~isfinite([v1 v2])) || ...
                            v1 >= v2
                        
                        uialert( ...
                            app.UIFigure, ...
                            'Absolute range requires minimum < maximum.', ...
                            'Optimize scan');
                        
                        return
                    end
                    
                    
                    rule.Type = ...
                        "Absolute";
                    
                    
                    rule.AbsoluteLow = ...
                        v1;
                    
                    
                    rule.AbsoluteHigh = ...
                        v2;
                    
            end
            
            
            ok = true;
            
        end
        
        function [map2D, mapTitle, mapUnit] = ...
                calculateSpatialMapFromRecipe( ...
                app, ...
                cubeData, ...
                wn, ...
                recipe)
            
            wn = ...
                double(wn(:));
            
            
            mapType = ...
                string(recipe.MapType);
            
            
            switch mapType
                
                case "Peak intensity"
                    
                    [~, idx] = ...
                        min(abs(wn - recipe.PeakWn));
                    
                    
                    map2D = ...
                        double(cubeData(:,:,idx));
                    
                    
                    mapTitle = ...
                        sprintf( ...
                        'Peak %.2f cm^{-1}', ...
                        wn(idx));
                    
                    
                    mapUnit = ...
                        "Intensity (a.u.)";
                    
                    
                case "Integrated band"
                    
                    map2D = ...
                        app.integrateSpatialBand( ...
                        cubeData, ...
                        wn, ...
                        recipe.Band1);
                    
                    
                    mapTitle = ...
                        sprintf( ...
                        'Integrated %.0f–%.0f cm^{-1}', ...
                        recipe.Band1(1), ...
                        recipe.Band1(2));
                    
                    
                    mapUnit = ...
                        "Integrated intensity";
                    
                    
                case "Baseline-corrected band area"
                    
                    map2D = ...
                        app.integrateSpatialBandLocalBaseline( ...
                        cubeData, ...
                        wn, ...
                        recipe.Band1);
                    
                    
                    mapTitle = ...
                        sprintf( ...
                        'Baseline-corrected area %.0f–%.0f cm^{-1}', ...
                        recipe.Band1(1), ...
                        recipe.Band1(2));
                    
                    
                    mapUnit = ...
                        "Baseline-corrected area";
                    
                    
                case "Normalized band"
                    
                    numerator = ...
                        app.integrateSpatialBand( ...
                        cubeData, ...
                        wn, ...
                        recipe.Band1);
                    
                    
                    totalArea = ...
                        trapz( ...
                        wn, ...
                        abs(double(cubeData)), ...
                        3);
                    
                    
                    map2D = ...
                        nan(size(numerator));
                    
                    
                    valid = ...
                        isfinite(totalArea) & ...
                        abs(totalArea) > eps;
                    
                    
                    map2D(valid) = ...
                        numerator(valid) ./ ...
                        totalArea(valid);
                    
                    
                    mapTitle = ...
                        sprintf( ...
                        'Normalized %.0f–%.0f cm^{-1}', ...
                        recipe.Band1(1), ...
                        recipe.Band1(2));
                    
                    
                    mapUnit = ...
                        "Relative intensity";
                    
                    
                case { ...
                        "Ratio / composite score", ...
                        "Band ratio", ...
                        "Area ratio", ...
                        "Log ratio"}
                    
                    
                    % =========================================================
                    % SAVED / COMPOSITE RECIPE
                    % =========================================================
                    
                    if isfield(recipe,'RatioMode') && ...
                            string(recipe.RatioMode) == ...
                            "Definition"
                        
                        
                        if ~isfield(recipe,'RatioDefinition') || ...
                                isempty(recipe.RatioDefinition)
                            
                            error( ...
                                'Spatial ratio recipe snapshot is missing.');
                            
                        end
                        
                        
                        defStruct = ...
                            recipe.RatioDefinition;
                        
                        
                        def = ...
                            struct2table( ...
                            defStruct, ...
                            'AsArray', ...
                            true);
                        
                        
                        map2D = ...
                            app.computeSpatialRatioDefinitionMap( ...
                            cubeData, ...
                            wn, ...
                            def);
                        
                        
                        mapTitle = ...
                            "Ratio / score: " + ...
                            string(recipe.RatioName);
                        
                        
                        if isfield(recipe,'RatioFormula')
                            
                            mapTitle = ...
                                mapTitle + ...
                                " | " + ...
                                string(recipe.RatioFormula);
                            
                        end
                        
                        
                        % =========================================================
                        % MANUAL TWO-BAND RATIO
                        % =========================================================
                        
                    else
                        
                        % =========================================================
                        % MANUAL TWO-BAND RATIO
                        % =========================================================
                        
                        switch mapType
                            
                            % ---------------------------------------------------------
                            % BAND RATIO
                            %
                            % Mean spectral intensity within each selected band.
                            % Using the mean avoids dependence on band width.
                            % ---------------------------------------------------------
                            case "Band ratio"
                                
                                numerator = ...
                                    app.meanSpatialBand( ...
                                    cubeData, ...
                                    wn, ...
                                    recipe.Band1);
                                
                                denominator = ...
                                    app.meanSpatialBand( ...
                                    cubeData, ...
                                    wn, ...
                                    recipe.Band2);
                                
                                
                                % ---------------------------------------------------------
                                % AREA RATIO
                                % ---------------------------------------------------------
                            case "Area ratio"
                                
                                numerator = ...
                                    app.integrateSpatialBand( ...
                                    cubeData, ...
                                    wn, ...
                                    recipe.Band1);
                                
                                denominator = ...
                                    app.integrateSpatialBand( ...
                                    cubeData, ...
                                    wn, ...
                                    recipe.Band2);
                                
                                
                                % ---------------------------------------------------------
                                % LOG RATIO
                                %
                                % Manual Log ratio uses Area ratio as its underlying ratio.
                                % log10 is applied below by existing code.
                                % ---------------------------------------------------------
                            case "Log ratio"
                                
                                numerator = ...
                                    app.integrateSpatialBand( ...
                                    cubeData, ...
                                    wn, ...
                                    recipe.Band1);
                                
                                denominator = ...
                                    app.integrateSpatialBand( ...
                                    cubeData, ...
                                    wn, ...
                                    recipe.Band2);
                                
                                
                                % ---------------------------------------------------------
                                % MANUAL RATIO / COMPOSITE SCORE
                                %
                                % Without a saved feature definition there is no composite
                                % formula to reproduce. Use Area ratio as the explicit default.
                                % ---------------------------------------------------------
                            otherwise
                                
                                numerator = ...
                                    app.integrateSpatialBand( ...
                                    cubeData, ...
                                    wn, ...
                                    recipe.Band1);
                                
                                denominator = ...
                                    app.integrateSpatialBand( ...
                                    cubeData, ...
                                    wn, ...
                                    recipe.Band2);
                                
                        end
                        
                        
                        map2D = ...
                            app.safeSpatialRatio( ...
                            numerator, ...
                            denominator);
                        
                        
                        mapTitle = ...
                            sprintf( ...
                            'Ratio %.0f–%.0f / %.0f–%.0f', ...
                            recipe.Band1(1), ...
                            recipe.Band1(2), ...
                            recipe.Band2(1), ...
                            recipe.Band2(2));
                        
                    end
                    
                    
                    % =========================================================
                    % OPTIONAL LOG10 TRANSFORMATION
                    % =========================================================
                    
                    applyLog = ...
                        false;
                    
                    
                    if isfield(recipe,'ApplyLog10')
                        
                        applyLog = ...
                            logical(recipe.ApplyLog10);
                        
                    elseif mapType == "Log ratio"
                        
                        applyLog = ...
                            true;
                        
                    end
                    
                    
                    if applyLog
                        
                        positive = ...
                            isfinite(map2D) & ...
                            map2D > 0;
                        
                        
                        temp = ...
                            nan(size(map2D));
                        
                        
                        temp(positive) = ...
                            log10( ...
                            map2D(positive));
                        
                        
                        map2D = ...
                            temp;
                        
                        
                        mapTitle = ...
                            "log10 " + ...
                            string(mapTitle);
                        
                        
                        mapUnit = ...
                            "log10 ratio / score";
                        
                    else
                        
                        mapUnit = ...
                            "Ratio / score";
                        
                    end
                    
                    
                case "Peak position"
                    
                    map2D = ...
                        app.spatialPeakPositionMap( ...
                        cubeData, ...
                        wn, ...
                        recipe.Band1);
                    
                    
                    mapTitle = ...
                        sprintf( ...
                        'Peak position %.0f–%.0f cm^{-1}', ...
                        recipe.Band1(1), ...
                        recipe.Band1(2));
                    
                    
                    mapUnit = ...
                        "cm^{-1}";
                    
                    
                case "FWHM"
                    
                    map2D = ...
                        app.spatialFWHMMap( ...
                        cubeData, ...
                        wn, ...
                        recipe.Band1);
                    
                    
                    mapTitle = ...
                        sprintf( ...
                        'FWHM %.0f–%.0f cm^{-1}', ...
                        recipe.Band1(1), ...
                        recipe.Band1(2));
                    
                    
                    mapUnit = ...
                        "cm^{-1}";
                    
                    
                case { ...
                        "Pearson similarity", ...
                        "Cosine similarity", ...
                        "Spectral angle"}
                    
                    [map2D, mapUnit] = ...
                        app.spatialSimilarityMap( ...
                        cubeData, ...
                        recipe);
                    
                    
                    mapTitle = ...
                        mapType + ...
                        " | " + ...
                        string(recipe.ReferenceMode);
                    
                    
                case "PCA score"
                    
                    map2D = ...
                        app.spatialPCAScoreMap( ...
                        cubeData, ...
                        wn, ...
                        recipe.PC);
                    
                    
                    mapTitle = ...
                        sprintf( ...
                        'PCA score PC%d', ...
                        recipe.PC);
                    
                    
                    mapUnit = ...
                        "PCA score";
                    
                    
                case "Cluster / class map"
                    
                    map2D = ...
                        app.spatialClassMap( ...
                        cubeData, ...
                        wn);
                    
                    
                    mapTitle = ...
                        "Classification map | " + ...
                        app.classModelType;
                    
                    
                    mapUnit = ...
                        "Class index";
                    
                    
                otherwise
                    
                    error( ...
                        'Unsupported spatial map type: %s', ...
                        mapType);
                    
            end
            
        end
        
        
        function map2D = integrateSpatialBand( ...
                app, ...
                cubeData, ...
                wn, ...
                bandRange)
            
            %#ok<INUSD>
            
            wn = ...
                double(wn(:));
            
            lo = ...
                min(bandRange);
            
            hi = ...
                max(bandRange);
            
            
            mask = ...
                wn >= lo & ...
                wn <= hi;
            
            
            if nnz(mask) < 2
                
                error( ...
                    ['Band %.2f–%.2f cm^-1 contains fewer than ' ...
                    'two wavenumber points.'], ...
                    lo, ...
                    hi);
                
            end
            
            
            % =========================================================
            % EXTRACT BAND
            % =========================================================
            
            xBand = ...
                wn(mask);
            
            Y = ...
                double(cubeData(:,:,mask));
            
            
            % =========================================================
            % FORCE ASCENDING WAVENUMBER
            %
            % trapz changes sign when X is descending.
            % Spatial integration must therefore use the same orientation
            % rule as single-spectrum Ratio Area.
            % =========================================================
            
            [xBand,order] = ...
                sort(xBand,'ascend');
            
            Y = ...
                Y(:,:,order);
            
            
            % =========================================================
            % INTEGRATE
            % =========================================================
            
            map2D = ...
                trapz( ...
                xBand, ...
                Y, ...
                3);
            
        end


        function areaMap = integrateSpatialBandLocalBaseline( ...
                app, ...
                cubeData, ...
                wn, ...
                bandRange)
            lo = min(bandRange);
            hi = max(bandRange);
            mask = ...
                wn >= lo & ...
                wn <= hi;
            idx = find(mask);
            if numel(idx) < 3
                error( ...
                    ['Baseline-corrected band area requires at least ' ...
                    'three spectral points in the selected band.']);
            end
            x = ...
                wn(idx);
            Y = ...
                double(cubeData(:,:,idx));
            % =========================================================
            % FORCE ASCENDING WAVENUMBER
            % =========================================================
            
            [x,order] = ...
                sort( ...
                double(x(:)), ...
                'ascend');
            
            Y = ...
                Y(:,:,order);
            
            yLeft = ...
                Y(:,:,1);
            yRight = ...
                Y(:,:,end);
            xLeft = x(1);
            xRight = x(end);
            alpha = ...
                reshape( ...
                (x - xLeft) ./ ...
                (xRight - xLeft), ...
                1, ...
                1, ...
                []);
            baseline = ...
                yLeft .* (1 - alpha) + ...
                yRight .* alpha;
            corrected = ...
                Y - baseline;
            areaMap = ...
                trapz( ...
                x, ...
                corrected, ...
                3);
        end
        
        
        function ratioMap = safeSpatialRatio( ...
                app, ...
                numerator, ...
                denominator)
            
            numerator = ...
                double(numerator);
            
            denominator = ...
                double(denominator);
            
            
            finiteDen = ...
                denominator(isfinite(denominator));
            
            
            if isempty(finiteDen)
                
                ratioMap = ...
                    nan(size(numerator));
                
                return
            end
            
            
            % =========================================================
            % ROBUST MINIMUM DENOMINATOR
            %
            % Prevent isolated near-zero pixels from exploding.
            % =========================================================
            positiveDen = ...
                abs(finiteDen);
            
            positiveDen = ...
                positiveDen(positiveDen > 0);
            
            
            if isempty(positiveDen)
                
                threshold = eps;
                
            else
                
                threshold = ...
                    max( ...
                    eps, ...
                    prctile( ...
                    positiveDen, ...
                    1) * 0.1);
                
            end
            
            
            valid = ...
                isfinite(numerator) & ...
                isfinite(denominator) & ...
                abs(denominator) > threshold;
            
            
            ratioMap = ...
                nan(size(numerator));
            
            
            ratioMap(valid) = ...
                numerator(valid) ./ ...
                denominator(valid);
            
        end


        function plotSpatialChemicalMap( ...
                app, ...
                ax, ...
                map2D, ...
                datasetName, ...
                mapTitle)
            
            cla(ax, 'reset');
            
            
            img = ...
                imagesc( ...
                ax, ...
                map2D);
            
            
            if isprop(img, 'Interpolation')
                img.Interpolation = 'nearest';
            end
            
            
            axis(ax, 'image');
            axis(ax, 'tight');
            
            ax.Box = 'on';
            
            
            xlabel(ax, 'X pixel');
            ylabel(ax, 'Y pixel');
            
            
            title( ...
                ax, ...
                { ...
                char(datasetName); ...
                char(mapTitle)}, ...
                'Interpreter', ...
                'none');
            
            
            app.applySpatialColormap(ax);
            
            
            cb = colorbar(ax);
            
            %#ok<NASGU>
            cb;
            
            
            grid(ax, 'off');
            
        end


        function applySpatialColormap(app, ax)
            
            colorName = ...
                lower( ...
                string( ...
                app.ColormapDropDown.Value));
            
            
            switch colorName
                
                case "hot"
                    colormap(ax, hot);
                    
                case "turbo"
                    colormap(ax, turbo);
                    
                case "parula"
                    colormap(ax, parula);
                    
                case "jet"
                    colormap(ax, jet);
                    
                case "bone"
                    colormap(ax, bone);
                    
                case "gray"
                    colormap(ax, gray);
                    
                otherwise
                    colormap(ax, hot);
                    
            end
            
        end


        function refreshSpatialMapAppearance(app)
            
            axesList = ...
                app.getSpatialAxes();
            
            
            for k = 1:6
                
                if app.spatialPanels(k).Calculated && ...
                        ~isempty(app.spatialPanels(k).MapData)
                    
                    app.applySpatialColormap( ...
                        axesList{k});
                    
                end
                
            end
            
        end


        function applySpatialColorScaling(app)
            
            axesList = ...
                app.getSpatialAxes();
            
            validPanels = [];
            
            for k = 1:6
                
                if app.spatialPanels(k).Calculated && ...
                        ~isempty(app.spatialPanels(k).MapData)
                    
                    validPanels(end+1) = k; %#ok<AGROW>
                    
                end
                
            end
            
            
            if isempty(validPanels)
                return
            end
            
            
            scaleMode = ...
                string( ...
                app.ColorscaleDropDown.Value);
            
            
            % =========================================================
            % INDIVIDUAL
            % =========================================================
            if scaleMode == "Individual"
                
                for k = validPanels
                    
                    vals = ...
                        double( ...
                        app.spatialPanels(k).MapData(:));
                    
                    vals = ...
                        vals(isfinite(vals));
                    
                    
                    if isempty(vals)
                        continue
                    end
                    
                    
                    lo = prctile(vals, 1);
                    hi = prctile(vals, 99);
                    
                    
                    if isfinite(lo) && ...
                            isfinite(hi) && ...
                            hi > lo
                        
                        clim( ...
                            axesList{k}, ...
                            [lo hi]);
                        
                        app.spatialPanels(k).CLim = ...
                            [lo hi];
                        
                    end
                end
                
                return
            end
            
            
            % =========================================================
            % SHARED SCALE REQUIRES COMPATIBLE MAPS
            % =========================================================
            mapTypes = strings(numel(validPanels),1);
            mapUnits = strings(numel(validPanels),1);
            
            for i = 1:numel(validPanels)
                
                k = validPanels(i);
                
                if isfield( ...
                        app.spatialPanels(k).Recipe, ...
                        'MapType')
                    
                    mapTypes(i) = ...
                        string( ...
                        app.spatialPanels(k).Recipe.MapType);
                    
                end
                
                mapUnits(i) = ...
                    string( ...
                    app.spatialPanels(k).MapUnit);
                
            end
            
            
            if numel(unique(mapUnits)) > 1
                
                app.ColorscaleDropDown.Value = ...
                    'Individual';
                
                app.spatialColorScale = ...
                    "Individual";
                
                app.applySpatialColorScaling();
                
                return
            end
            
            
            allVals = [];
            
            
            for k = validPanels
                
                vals = ...
                    double( ...
                    app.spatialPanels(k).MapData(:));
                
                vals = ...
                    vals(isfinite(vals));
                
                allVals = ...
                    [allVals; vals]; %#ok<AGROW>
                
            end
            
            
            if isempty(allVals)
                return
            end
            
            
            % Robust shared display, avoids a single extreme pixel
            % controlling the entire six-panel visualization.
            lo = ...
                prctile(allVals, 1);
            
            hi = ...
                prctile(allVals, 99);
            
            
            if ~isfinite(lo) || ...
                    ~isfinite(hi) || ...
                    hi <= lo
                
                lo = min(allVals);
                hi = max(allVals);
                
            end
            
            
            if isfinite(lo) && ...
                    isfinite(hi) && ...
                    hi > lo
                
                for k = validPanels
                    
                    clim( ...
                        axesList{k}, ...
                        [lo hi]);
                    
                    app.spatialPanels(k).CLim = ...
                        [lo hi];
                    
                end
                
            end
            
        end


        function feature = ...
                computeRatioPeakFeature( ...
                app, ...
                spectraRows, ...
                wn, ...
                lowWN, ...
                highWN, ...
                centerWN, ...
                featureType)
            
            wn = ...
                double(wn(:));
            
            spectraRows = ...
                double(spectraRows);
            
            
            nSpectra = ...
                size(spectraRows,1);
            
            
            feature = ...
                nan(nSpectra,1);
            
            
            lo = ...
                min(lowWN, highWN);
            
            hi = ...
                max(lowWN, highWN);
            
            
            featureType = ...
                string(featureType);
            
            
            switch featureType
                
                case "Exact intensity"
                    
                    [~, idx] = ...
                        min(abs(wn - centerWN));
                    
                    feature = ...
                        spectraRows(:,idx);
                    
                    
                case "Area"
                    
                    mask = ...
                        wn >= lo & ...
                        wn <= hi;
                    
                    
                    if nnz(mask) < 2
                        return
                    end
                    
                    
                    x = ...
                        wn(mask);
                    
                    Y = ...
                        spectraRows(:,mask);
                    
                    
                    [x, order] = ...
                        sort(x, 'ascend');
                    
                    Y = ...
                        Y(:,order);
                    
                    
                    feature = ...
                        trapz(x, Y, 2);
                    
                    
                case "1/FWHM"
                    
                    mask = ...
                        wn >= lo & ...
                        wn <= hi;
                    
                    
                    if nnz(mask) < 5
                        return
                    end
                    
                    
                    x = ...
                        wn(mask);
                    
                    Y = ...
                        spectraRows(:,mask);
                    
                    
                    for s = 1:nSpectra
                        
                        feature(s) = ...
                            app.computeInverseFWHMSingle( ...
                            x, ...
                            Y(s,:));
                        
                    end
                    
                    
                otherwise
                    
                    error( ...
                        'Unsupported Ratio feature type: %s', ...
                        featureType);
                    
            end
            
        end
        
        
        function inverseFWHM = ...
                computeInverseFWHMSingle(app, wnBand, yBand)
            
            %#ok<INUSD>
            
            inverseFWHM = NaN;
            
            
            x = ...
                double(wnBand(:));
            
            y = ...
                double(yBand(:));
            
            
            valid = ...
                isfinite(x) & ...
                isfinite(y);
            
            
            x = x(valid);
            y = y(valid);
            
            
            if numel(x) < 5
                return
            end
            
            
            [x, order] = ...
                sort(x, 'ascend');
            
            y = ...
                y(order);
            
            
            [x, uniqueIdx] = ...
                unique(x, 'stable');
            
            y = ...
                y(uniqueIdx);
            
            
            if numel(x) < 5
                return
            end
            
            
            dx = ...
                x(end) - x(1);
            
            
            if ~isfinite(dx) || dx <= 0
                return
            end
            
            
            baseline = ...
                y(1) + ...
                (y(end)-y(1)) .* ...
                (x-x(1)) ./ dx;
            
            
            yc = ...
                y - baseline;
            
            
            [peakHeight, peakIdx] = ...
                max(yc);
            
            
            if ~isfinite(peakHeight) || ...
                    peakHeight <= 0 || ...
                    peakIdx <= 1 || ...
                    peakIdx >= numel(x)
                
                return
            end
            
            
            halfHeight = ...
                peakHeight / 2;
            
            
            leftIdx = ...
                find( ...
                yc(1:peakIdx) <= halfHeight, ...
                1, ...
                'last');
            
            
            if isempty(leftIdx) || ...
                    leftIdx >= peakIdx
                
                return
            end
            
            
            i1 = leftIdx;
            i2 = leftIdx + 1;
            
            
            if yc(i2) == yc(i1)
                return
            end
            
            
            xLeft = ...
                x(i1) + ...
                (halfHeight-yc(i1)) .* ...
                (x(i2)-x(i1)) ./ ...
                (yc(i2)-yc(i1));
            
            
            rightRelative = ...
                find( ...
                yc(peakIdx:end) <= halfHeight, ...
                1, ...
                'first');
            
            
            if isempty(rightRelative) || ...
                    rightRelative <= 1
                
                return
            end
            
            
            j2 = ...
                peakIdx + rightRelative - 1;
            
            j1 = ...
                j2 - 1;
            
            
            if yc(j2) == yc(j1)
                return
            end
            
            
            xRight = ...
                x(j1) + ...
                (halfHeight-yc(j1)) .* ...
                (x(j2)-x(j1)) ./ ...
                (yc(j2)-yc(j1));
            
            
            fwhm = ...
                xRight - xLeft;
            
            
            if isfinite(fwhm) && fwhm > 0
                
                inverseFWHM = ...
                    1 / fwhm;
                
            end
            
        end


        function appendSpatialRecipeHistory( ...
                app, ...
                recipe)
            
            newIndex = ...
                numel(app.spatialRecipeHistory) + 1;
            
            
            entry = struct();
            
            entry.RecipeID = ...
                "MAP_" + ...
                string(datetime( ...
                'now', ...
                'Format', ...
                'yyyyMMdd_HHmmss_SSS'));
            
            entry.MapType = ...
                string(recipe.MapType);
            
            entry.MapSource = ...
                string(recipe.MapSource);
            
            entry.Parameters = ...
                recipe;
            
            entry.ColorMap = ...
                string(app.ColormapDropDown.Value);
            
            entry.ColorScale = ...
                string(app.ColorscaleDropDown.Value);
            
            entry.CreatedAt = ...
                string(datetime( ...
                'now', ...
                'Format', ...
                'yyyy-MM-dd HH:mm:ss'));
            
            entry.User = ...
                app.homeUser;
            
            
            app.spatialRecipeHistory(newIndex) = ...
                entry;
            
        end


        function saveSpatialMetadata(app, showMessage)
            
            if nargin < 2
                showMessage = true;
            end
            
            
            if strlength(app.singleOutputSessionFolder) == 0
                
                app.createSingleOutputSessionFolder();
                
            end
            
            
            spatialFolder = ...
                fullfile( ...
                app.singleOutputSessionFolder, ...
                '04_Spatial_Distribution');
            
            
            panelFolder = ...
                fullfile( ...
                spatialFolder, ...
                'panels');
            
            
            if ~isfolder(spatialFolder)
                mkdir(spatialFolder);
            end
            
            if ~isfolder(panelFolder)
                mkdir(panelFolder);
            end
            
            
            % =========================================================
            % SMALL SPATIAL METADATA STRUCTURE
            %
            % DO NOT INCLUDE:
            %   MapData
            %   ReferenceData
            %   spatialCache cubes
            % =========================================================
            M = struct();
            
            M.SchemaVersion = ...
                app.spatialMetadataVersion;
            
            M.SavedAt = ...
                string(datetime( ...
                'now', ...
                'Format', ...
                'yyyy-MM-dd HH:mm:ss'));
            
            M.SessionID = ...
                app.singleSessionID;
            
            M.SessionName = ...
                app.singleSessionName;
            
            M.User = ...
                app.homeUser;
            
            M.SourceInputFolder = ...
                app.singleInputFolder;
            
            M.DatasetRegistry = ...
                app.spatialDatasets;
            
            M.RecipeHistory = ...
                app.spatialRecipeHistory;
            
            M.ColorMap = ...
                string(app.ColormapDropDown.Value);
            
            M.ColorScale = ...
                string(app.ColorscaleDropDown.Value);
            
            M.ReferenceBand = ...
                app.spatialReferenceBand;
            
            
            % =========================================================
            % STORE PANEL RECIPES WITHOUT MAP MATRICES
            % =========================================================
            panelMetadata = repmat( ...
                struct( ...
                'PanelID', NaN, ...
                'DatasetID', "", ...
                'DatasetName', "", ...
                'Recipe', struct(), ...
                'MapTitle', "", ...
                'MapUnit', "", ...
                'Calculated', false, ...
                'CalculatedAt', "", ...
                'CLim', [NaN NaN]), ...
                6, ...
                1);
            
            
            for k = 1:6
                
                P = app.spatialPanels(k);
                
                panelMetadata(k).PanelID = ...
                    P.PanelID;
                
                panelMetadata(k).DatasetID = ...
                    P.DatasetID;
                
                panelMetadata(k).DatasetName = ...
                    P.DatasetName;
                
                panelMetadata(k).Recipe = ...
                    P.Recipe;
                
                panelMetadata(k).MapTitle = ...
                    P.MapTitle;
                
                panelMetadata(k).MapUnit = ...
                    P.MapUnit;
                
                panelMetadata(k).Calculated = ...
                    P.Calculated;
                
                panelMetadata(k).CalculatedAt = ...
                    P.CalculatedAt;
                
                panelMetadata(k).CLim = ...
                    P.CLim;
                
            end
            
            
            M.Panels = ...
                panelMetadata;
            
            
            save( ...
                fullfile( ...
                spatialFolder, ...
                'spatial_metadata.mat'), ...
                'M');
            
            
            % =========================================================
            % HUMAN-READABLE DATASET TABLE
            % =========================================================
            if ~isempty(app.spatialDatasets)
                
                D = app.spatialDatasets;
                
                datasetTable = table( ...
                    string({D.ID})', ...
                    string({D.DisplayName})', ...
                    string({D.GroupName})', ...
                    string({D.SourceScanName})', ...
                    string({D.RawCubePath})', ...
                    string({D.ProcessedCubePath})', ...
                    string({D.SourceStage})', ...
                    'VariableNames', { ...
                    'DatasetID', ...
                    'DisplayName', ...
                    'GroupName', ...
                    'SourceScanName', ...
                    'RawCubePath', ...
                    'ProcessedCubePath', ...
                    'SourceStage'});
                
                
                writetable( ...
                    datasetTable, ...
                    fullfile( ...
                    spatialFolder, ...
                    'spatial_datasets.tsv'), ...
                    'FileType', ...
                    'text', ...
                    'Delimiter', ...
                    '\t');
                
            end
            
            
            % =========================================================
            % PANEL TEXT RECIPES
            % =========================================================
            for k = 1:6
                
                app.writeSpatialPanelRecipeText( ...
                    panelMetadata(k), ...
                    fullfile( ...
                    panelFolder, ...
                    sprintf( ...
                    'panel_%02d_recipe.txt', ...
                    k)));
                
            end
            
            
            if showMessage
                
                msgbox( ...
                    sprintf( ...
                    ['Spatial metadata saved.\n\n' ...
                    'No Raman cube or map matrix was duplicated.\n\n' ...
                    'Folder:\n%s'], ...
                    spatialFolder), ...
                    'Spatial metadata');
                
            end
            
        end


        function [mask, ranges, modeText, ok] = ...
                askTemporaryWnMask( ...
                app, ...
                wn, ...
                analysisName)
            
            wn = ...
                double(wn(:));
            
            
            mask = ...
                true(size(wn));
            
            ranges = ...
                [min(wn) max(wn)];
            
            modeText = ...
                "Full available range";
            
            ok = false;
            
            
            choices = { ...
                'Use current selected WN range(s)', ...
                'Use full available spectrum', ...
                'Custom WN range(s) for this run only'};
            
            
            [sel, accepted] = ...
                listdlg( ...
                'PromptString', ...
                sprintf( ...
                '%s: choose wavenumber range policy', ...
                analysisName), ...
                'SelectionMode', ...
                'single', ...
                'ListString', ...
                choices, ...
                'InitialValue', ...
                1, ...
                'ListSize', ...
                [390 145]);
            
            
            if ~accepted
                return
            end
            
            
            switch sel
                
                % =====================================================
                % CURRENT SPECTRAL DEFINITION
                % =====================================================
                case 1
                    
                    if ~isempty(app.spectralDef) && ...
                            isfield(app.spectralDef,'roiMask') && ...
                            numel(app.spectralDef.roiMask) == numel(wn) && ...
                            any(app.spectralDef.roiMask)
                        
                        mask = ...
                            logical(app.spectralDef.roiMask(:));
                        
                        
                        ranges = ...
                            app.maskToWnRanges( ...
                            wn, ...
                            mask);
                        
                        
                        modeText = ...
                            "Current selected WN range(s)";
                        
                    elseif ~isempty(app.spectralDef) && ...
                            isfield(app.spectralDef,'roiRanges') && ...
                            ~isempty(app.spectralDef.roiRanges)
                        
                        ranges = ...
                            double(app.spectralDef.roiRanges);
                        
                        
                        mask = ...
                            app.rangesToWnMask( ...
                            wn, ...
                            ranges);
                        
                        
                        modeText = ...
                            "Current selected WN range(s)";
                        
                    else
                        
                        mask = ...
                            true(size(wn));
                        
                        ranges = ...
                            [min(wn) max(wn)];
                        
                        modeText = ...
                            "Full available range";
                        
                    end
                    
                    
                    % =====================================================
                    % FULL
                    % =====================================================
                case 2
                    
                    mask = ...
                        true(size(wn));
                    
                    ranges = ...
                        [min(wn) max(wn)];
                    
                    modeText = ...
                        "Full available range";
                    
                    
                    % =====================================================
                    % CUSTOM MULTI-RANGE
                    % =====================================================
                case 3
                    
                    answer = ...
                        inputdlg( ...
                        {sprintf([ ...
                        'Enter one WN range per line or separate by semicolon.\n' ...
                        'Example:\n800 1200; 1300 1800; 2800 3100'])}, ...
                        sprintf('%s custom WN ranges', analysisName), ...
                        [5 60], ...
                        {''});
                    
                    
                    if isempty(answer)
                        return
                    end
                    
                    
                    txt = ...
                        string(answer{1});
                    
                    
                    txt = ...
                        replace(txt, newline, ";");
                    
                    
                    segments = ...
                        split(txt, ";");
                    
                    
                    parsedRanges = ...
                        zeros(0,2);
                    
                    
                    for k = 1:numel(segments)
                        
                        nums = ...
                            sscanf( ...
                            char(segments(k)), ...
                            '%f');
                        
                        
                        if numel(nums) < 2
                            continue
                        end
                        
                        
                        lo = ...
                            min(nums(1), nums(2));
                        
                        hi = ...
                            max(nums(1), nums(2));
                        
                        
                        lo = ...
                            max(lo, min(wn));
                        
                        hi = ...
                            min(hi, max(wn));
                        
                        
                        if lo < hi
                            
                            parsedRanges(end+1,:) = ...
                                [lo hi]; %#ok<AGROW>
                            
                        end
                        
                    end
                    
                    
                    if isempty(parsedRanges)
                        
                        uialert( ...
                            app.UIFigure, ...
                            'No valid WN range was entered.', ...
                            analysisName);
                        
                        return
                    end
                    
                    
                    ranges = ...
                        parsedRanges;
                    
                    
                    mask = ...
                        app.rangesToWnMask( ...
                        wn, ...
                        ranges);
                    
                    
                    modeText = ...
                        "Custom WN range(s) — current run only";
                    
            end
            
            
            if ~any(mask)
                
                uialert( ...
                    app.UIFigure, ...
                    'Selected WN ranges contain no spectral points.', ...
                    analysisName);
                
                return
            end
            
            
            ok = true;
            
        end
        
        
        function mask = ...
                rangesToWnMask(app, wn, ranges)
            
            %#ok<INUSD>
            
            wn = ...
                double(wn(:));
            
            
            mask = ...
                false(size(wn));
            
            
            for r = 1:size(ranges,1)
                
                lo = ...
                    min(ranges(r,:));
                
                hi = ...
                    max(ranges(r,:));
                
                
                mask = ...
                    mask | ...
                    (wn >= lo & wn <= hi);
                
            end
            
        end
        
        
        function ranges = ...
                maskToWnRanges(app, wn, mask)
            
            %#ok<INUSD>
            
            wn = ...
                double(wn(:));
            
            mask = ...
                logical(mask(:));
            
            
            idx = ...
                find(mask);
            
            
            if isempty(idx)
                
                ranges = ...
                    zeros(0,2);
                
                return
            end
            
            
            breaks = ...
                [true; diff(idx) > 1; true];
            
            
            edge = ...
                find(breaks);
            
            
            ranges = ...
                zeros(numel(edge)-1,2);
            
            
            for k = 1:numel(edge)-1
                
                runIdx = ...
                    idx(edge(k):edge(k+1)-1);
                
                
                ranges(k,:) = ...
                    [ ...
                    min(wn(runIdx)), ...
                    max(wn(runIdx)) ...
                    ];
                
            end
            
        end

        function writeSpatialPanelRecipeText( ...
                app, ...
                P, ...
                filePath)
            
            fid = fopen(filePath, 'w');
            
            if fid < 0
                return
            end
            
            cleaner = ...
                onCleanup(@() fclose(fid)); %#ok<NASGU>
            
            
            fprintf(fid, ...
                'RAMANCUBE SPATIAL MAP RECIPE\n');
            
            fprintf(fid, ...
                '========================================\n\n');
            
            
            fprintf(fid, ...
                'Panel: %d\n', ...
                P.PanelID);
            
            fprintf(fid, ...
                'Dataset ID: %s\n', ...
                char(P.DatasetID));
            
            fprintf(fid, ...
                'Dataset: %s\n', ...
                char(P.DatasetName));
            
            fprintf(fid, ...
                'Calculated: %d\n', ...
                P.Calculated);
            
            fprintf(fid, ...
                'Calculated at: %s\n', ...
                char(P.CalculatedAt));
            
            fprintf(fid, ...
                'Map title: %s\n', ...
                char(P.MapTitle));
            
            fprintf(fid, ...
                'Map unit: %s\n', ...
                char(P.MapUnit));
            
            
            if isempty(P.Recipe) || ...
                    ~isstruct(P.Recipe)
                
                fprintf(fid, ...
                    '\nNo map recipe.\n');
                
                return
            end
            
            
            fprintf(fid, ...
                '\nMAP RECIPE\n');
            
            fprintf(fid, ...
                '----------------------------------------\n');
            
            
            fields = fieldnames(P.Recipe);
            
            
            for k = 1:numel(fields)
                
                fn = fields{k};
                
                value = ...
                    P.Recipe.(fn);
                
                
                if isnumeric(value)
                    
                    txt = ...
                        mat2str(value);
                    
                elseif isstring(value) || ...
                        ischar(value)
                    
                    txt = ...
                        char(string(value));
                    
                else
                    
                    txt = ...
                        sprintf( ...
                        '<%s>', ...
                        class(value));
                    
                end
                
                
                fprintf(fid, ...
                    '%s: %s\n', ...
                    fn, ...
                    txt);
                
            end
            
        end
        
        
        function restoreSpatialDistributionUI(app)
            
            if ~app.spatialInitialized
                
                app.initializeSpatialDistribution();
                return
            end
            
            
            % =========================================================
            % GLOBAL CONTROLS
            % =========================================================
            if any(strcmp( ...
                    app.MapsourceDropDown.Items, ...
                    app.spatialMapSource))
                
                app.MapsourceDropDown.Value = ...
                    char(app.spatialMapSource);
                
            end
            
            
            if any(strcmp( ...
                    app.DisplaymaptypeDropDown.Items, ...
                    app.spatialMapType))
                
                app.DisplaymaptypeDropDown.Value = ...
                    char(app.spatialMapType);
                
            end
            
            
            if any(strcmp( ...
                    app.ColormapDropDown.Items, ...
                    app.spatialColorMap))
                
                app.ColormapDropDown.Value = ...
                    char(app.spatialColorMap);
                
            end
            
            
            if any(strcmp( ...
                    app.ColorscaleDropDown.Items, ...
                    app.spatialColorScale))
                
                app.ColorscaleDropDown.Value = ...
                    char(app.spatialColorScale);
                
            end
            
            
            % =========================================================
            % DROPDOWNS
            % =========================================================
            app.refreshSpatialDatasetDropdowns();
            
            dropdowns = ...
                app.getSpatialDatasetDropdowns();
            
            
            for k = 1:6
                
                datasetName = ...
                    string( ...
                    app.spatialPanels(k).DatasetName);
                
                
                if datasetName ~= "" && ...
                        any(string(dropdowns{k}.Items) == datasetName)
                    
                    dropdowns{k}.Value = ...
                        char(datasetName);
                    
                end
                
            end
            
            
            % =========================================================
            % RECONSTRUCT VISIBLE MAPS
            %
            % Map matrices were intentionally not saved.
            % =========================================================
            for k = 1:6
                
                if strlength( ...
                        app.spatialPanels(k).DatasetID) == 0
                    
                    continue
                end
                
                
                recipe = ...
                    app.spatialPanels(k).Recipe;
                
                
                if isempty(recipe) || ...
                        ~isstruct(recipe) || ...
                        ~isfield(recipe, 'MapType')
                    
                    app.handleSpatialDatasetSelection(k);
                    continue
                end
                
                
                try
                    
                    app.calculateSpatialPanelMap( ...
                        k, ...
                        recipe);
                    
                catch
                    
                    % If exact recipe reconstruction fails,
                    % at least show the reference scan.
                    app.handleSpatialDatasetSelection(k);
                    
                end
                
            end
            
            
            app.refreshSpatialMapAppearance();
            
            app.applySpatialColorScaling();
            
        end
        
        
        function ensureRatioDefinitionSchema(app)
            
            % =============================================================
            % SHARED RATIO / COMPOSITE SCORE RECIPE SCHEMA
            %
            % DefinitionType:
            %   SimpleRatio
            %   CompositeRatio
            %
            % VariableMap examples:
            %
            %   A=P2;B=P5
            %
            %   A=P1;B=P3;C=P8;D=P4;AB=P9
            %
            % Formula examples:
            %
            %   A/B
            %   (A+B+C)/D
            %   (A+B+C+D+G)/(AB+C)
            %
            % No feature vectors are stored here.
            % =============================================================
            
            requiredNames = { ...
                'RecipeID', ...
                'RatioName', ...
                'Formula', ...
                'FeatureType', ...
                'DefinitionType', ...
                'VariableMap', ...
                'NumPeak', ...
                'DenPeak', ...
                'NumLowWN', ...
                'NumHighWN', ...
                'NumCenterWN', ...
                'DenLowWN', ...
                'DenHighWN', ...
                'DenCenterWN', ...
                'Source', ...
                'CreatedAt'};
            
            
            % =============================================================
            % EMPTY REGISTRY
            % =============================================================
            
            if isempty(app.ratioDefinitions) || ...
                    ~istable(app.ratioDefinitions)
                
                app.ratioDefinitions = table( ...
                    strings(0,1), ...
                    strings(0,1), ...
                    strings(0,1), ...
                    strings(0,1), ...
                    strings(0,1), ...
                    strings(0,1), ...
                    nan(0,1), ...
                    nan(0,1), ...
                    nan(0,1), ...
                    nan(0,1), ...
                    nan(0,1), ...
                    nan(0,1), ...
                    nan(0,1), ...
                    nan(0,1), ...
                    strings(0,1), ...
                    strings(0,1), ...
                    'VariableNames', ...
                    requiredNames);
                
                return
            end
            
            
            % =============================================================
            % MIGRATE EXISTING SIMPLE RATIO TABLE
            % =============================================================
            
            T = ...
                app.ratioDefinitions;
            
            
            n = ...
                height(T);
            
            
            vars = ...
                string(T.Properties.VariableNames);
            
            
            % -------------------------------------------------------------
            % DefinitionType
            % -------------------------------------------------------------
            
            if ~ismember("DefinitionType", vars)
                
                T.DefinitionType = ...
                    repmat( ...
                    "SimpleRatio", ...
                    n, ...
                    1);
                
            end
            
            
            % -------------------------------------------------------------
            % VariableMap
            % -------------------------------------------------------------
            
            vars = ...
                string(T.Properties.VariableNames);
            
            
            if ~ismember("VariableMap", vars)
                
                T.VariableMap = ...
                    strings(n,1);
                
                
                for r = 1:n
                    
                    if ismember('NumPeak',T.Properties.VariableNames) && ...
                            ismember('DenPeak',T.Properties.VariableNames) && ...
                            isfinite(T.NumPeak(r)) && ...
                            isfinite(T.DenPeak(r))
                        
                        T.VariableMap(r) = ...
                            sprintf( ...
                            'A=P%d;B=P%d', ...
                            round(T.NumPeak(r)), ...
                            round(T.DenPeak(r)));
                        
                    end
                    
                end
                
            end
            
            
            % =============================================================
            % ADD ANY MISSING STRING COLUMNS
            % =============================================================
            
            stringFields = { ...
                'RecipeID', ...
                'RatioName', ...
                'Formula', ...
                'FeatureType', ...
                'DefinitionType', ...
                'VariableMap', ...
                'Source', ...
                'CreatedAt'};
            
            
            for k = 1:numel(stringFields)
                
                fieldName = ...
                    stringFields{k};
                
                
                if ~ismember( ...
                        fieldName, ...
                        T.Properties.VariableNames)
                    
                    T.(fieldName) = ...
                        strings(n,1);
                    
                end
                
            end
            
            
            % =============================================================
            % ADD ANY MISSING NUMERIC COLUMNS
            % =============================================================
            
            numericFields = { ...
                'NumPeak', ...
                'DenPeak', ...
                'NumLowWN', ...
                'NumHighWN', ...
                'NumCenterWN', ...
                'DenLowWN', ...
                'DenHighWN', ...
                'DenCenterWN'};
            
            
            for k = 1:numel(numericFields)
                
                fieldName = ...
                    numericFields{k};
                
                
                if ~ismember( ...
                        fieldName, ...
                        T.Properties.VariableNames)
                    
                    T.(fieldName) = ...
                        nan(n,1);
                    
                end
                
            end
            
            
            % =============================================================
            % NORMALIZE OLD SIMPLE RATIOS
            % =============================================================
            
            for r = 1:n
                
                if strlength(T.DefinitionType(r)) == 0
                    
                    if isfinite(T.NumPeak(r)) && ...
                            isfinite(T.DenPeak(r))
                        
                        T.DefinitionType(r) = ...
                            "SimpleRatio";
                        
                    else
                        
                        T.DefinitionType(r) = ...
                            "CompositeRatio";
                        
                    end
                    
                end
                
                
                if T.DefinitionType(r) == "SimpleRatio"
                    
                    if strlength(T.VariableMap(r)) == 0 && ...
                            isfinite(T.NumPeak(r)) && ...
                            isfinite(T.DenPeak(r))
                        
                        T.VariableMap(r) = ...
                            sprintf( ...
                            'A=P%d;B=P%d', ...
                            round(T.NumPeak(r)), ...
                            round(T.DenPeak(r)));
                        
                    end
                    
                    
                    % Old Ratio Note stored Formula = P2/P5.
                    % Convert recipe expression to generic A/B.
                    if strlength(T.Formula(r)) == 0 || ...
                            ~isempty(regexp( ...
                            char(T.Formula(r)), ...
                            '^P\d+/P\d+$', ...
                            'once'))
                        
                        T.Formula(r) = ...
                            "A/B";
                        
                    end
                    
                end
                
            end
            
            
            % =============================================================
            % CANONICAL COLUMN ORDER
            % =============================================================
            
            app.ratioDefinitions = ...
                T(:,requiredNames);
            
        end


        function def = normalizeRatioDefinitionRow(app, def)
            
            if isempty(def) || ...
                    ~istable(def) || ...
                    height(def) ~= 1
                
                error( ...
                    'Ratio definition must contain exactly one row.');
                
            end
            
            
            % =============================================================
            % CURRENT CANONICAL SCHEMA
            % =============================================================
            
            requiredNames = { ...
                'RecipeID', ...
                'RatioName', ...
                'Formula', ...
                'FeatureType', ...
                'DefinitionType', ...
                'VariableMap', ...
                'NumPeak', ...
                'DenPeak', ...
                'NumLowWN', ...
                'NumHighWN', ...
                'NumCenterWN', ...
                'DenLowWN', ...
                'DenHighWN', ...
                'DenCenterWN', ...
                'Source', ...
                'CreatedAt'};
            
            
            % =============================================================
            % STRING FIELDS
            % =============================================================
            
            stringFields = { ...
                'RecipeID', ...
                'RatioName', ...
                'Formula', ...
                'FeatureType', ...
                'DefinitionType', ...
                'VariableMap', ...
                'Source', ...
                'CreatedAt'};
            
            
            for k = 1:numel(stringFields)
                
                name = ...
                    stringFields{k};
                
                
                if ~ismember( ...
                        name, ...
                        def.Properties.VariableNames)
                    
                    def.(name) = "";
                    
                else
                    
                    def.(name) = ...
                        string(def.(name));
                    
                end
                
            end
            
            
            % =============================================================
            % NUMERIC FIELDS
            % =============================================================
            
            numericFields = { ...
                'NumPeak', ...
                'DenPeak', ...
                'NumLowWN', ...
                'NumHighWN', ...
                'NumCenterWN', ...
                'DenLowWN', ...
                'DenHighWN', ...
                'DenCenterWN'};
            
            
            for k = 1:numel(numericFields)
                
                name = ...
                    numericFields{k};
                
                
                if ~ismember( ...
                        name, ...
                        def.Properties.VariableNames)
                    
                    def.(name) = NaN;
                    
                else
                    
                    def.(name) = ...
                        double(def.(name));
                    
                end
                
            end
            
            
            % =============================================================
            % INFER DEFINITION TYPE
            % =============================================================
            
            if strlength(def.DefinitionType) == 0
                
                if isfinite(def.NumPeak) && ...
                        isfinite(def.DenPeak)
                    
                    def.DefinitionType = ...
                        "SimpleRatio";
                    
                else
                    
                    def.DefinitionType = ...
                        "CompositeRatio";
                    
                end
                
            end
            
            
            % =============================================================
            % OLD SIMPLE RATIO COMPATIBILITY
            % =============================================================
            
            if def.DefinitionType == "SimpleRatio"
                
                if strlength(def.VariableMap) == 0 && ...
                        isfinite(def.NumPeak) && ...
                        isfinite(def.DenPeak)
                    
                    def.VariableMap = ...
                        sprintf( ...
                        'A=P%d;B=P%d', ...
                        round(def.NumPeak), ...
                        round(def.DenPeak));
                    
                end
                
                
                if strlength(def.Formula) == 0 || ...
                        ~isempty(regexp( ...
                        char(def.Formula), ...
                        '^P\d+/P\d+$', ...
                        'once'))
                    
                    def.Formula = ...
                        "A/B";
                    
                end
                
            end
            
            
            def = ...
                def(:,requiredNames);
            
        end


        function key = makeRatioDefinitionKey(app, def)
            key = sprintf( ...
                '%s|%s|%.6f|%.6f|%.6f|%.6f', ...
                char(string(def.RatioName)), ...
                char(string(def.FeatureType)), ...
                double(def.NumLowWN), ...
                double(def.NumHighWN), ...
                double(def.DenLowWN), ...
                double(def.DenHighWN));
            
        end


        function [def, ok] = ...
                promptSimpleRatioDefinition(app)
            
            def = table();
            
            ok = false;
            
            
            if isempty(app.peakLibrary) || ...
                    ~istable(app.peakLibrary) || ...
                    height(app.peakLibrary) < 2
                
                uialert( ...
                    app.UIFigure, ...
                    'Peak library needs at least 2 defined peaks.', ...
                    'Add ratio');
                
                return
                
            end
            
            
            T = ...
                app.peakLibrary;
            
            
            peakList = ...
                strings(height(T),1);
            
            
            for k = 1:height(T)
                
                peakList(k) = ...
                    sprintf( ...
                    'P%d | %.1f–%.1f cm^-1 | %s', ...
                    k, ...
                    T.LowWN(k), ...
                    T.HighWN(k), ...
                    string(T.Interpretation(k)));
                
            end
            
            
            [numIdx, okNum] = ...
                listdlg( ...
                'PromptString', ...
                'Select numerator peak:', ...
                'SelectionMode', ...
                'single', ...
                'ListString', ...
                cellstr(peakList), ...
                'ListSize', ...
                [500 320]);
            
            
            if ~okNum
                return
            end
            
            
            [denIdx, okDen] = ...
                listdlg( ...
                'PromptString', ...
                'Select denominator peak:', ...
                'SelectionMode', ...
                'single', ...
                'ListString', ...
                cellstr(peakList), ...
                'ListSize', ...
                [500 320]);
            
            
            if ~okDen
                return
            end
            
            
            if numIdx == denIdx
                
                uialert( ...
                    app.UIFigure, ...
                    'Numerator and denominator must be different.', ...
                    'Ratio');
                
                return
                
            end
            
            
            featureChoices = { ...
                'Area', ...
                'Exact intensity', ...
                '1/FWHM'};
            
            
            [featureIdx, okFeature] = ...
                listdlg( ...
                'PromptString', ...
                'Peak feature used in this ratio:', ...
                'SelectionMode', ...
                'single', ...
                'ListString', ...
                featureChoices, ...
                'InitialValue', ...
                1);
            
            
            if ~okFeature
                return
            end
            
            
            RecipeID = ...
                "RATIO_" + ...
                string(datetime( ...
                'now', ...
                'Format', ...
                'yyyyMMdd_HHmmss_SSS'));
            
            
            RatioName = ...
                sprintf( ...
                'P%d/P%d', ...
                numIdx, ...
                denIdx);
            
            
            Formula = ...
                "A/B";
            
            
            FeatureType = ...
                string(featureChoices{featureIdx});
            
            
            DefinitionType = ...
                "SimpleRatio";
            
            
            VariableMap = ...
                sprintf( ...
                'A=P%d;B=P%d', ...
                numIdx, ...
                denIdx);
            
            
            NumPeak = ...
                double(numIdx);
            
            DenPeak = ...
                double(denIdx);
            
            
            NumLowWN = ...
                double(T.LowWN(numIdx));
            
            NumHighWN = ...
                double(T.HighWN(numIdx));
            
            NumCenterWN = ...
                double(T.CenterWN(numIdx));
            
            
            DenLowWN = ...
                double(T.LowWN(denIdx));
            
            DenHighWN = ...
                double(T.HighWN(denIdx));
            
            DenCenterWN = ...
                double(T.CenterWN(denIdx));
            
            
            Source = ...
                "Shared ratio menu";
            
            
            CreatedAt = ...
                string(datetime( ...
                'now', ...
                'Format', ...
                'yyyy-MM-dd HH:mm:ss'));
            
            
            def = table( ...
                RecipeID, ...
                string(RatioName), ...
                Formula, ...
                FeatureType, ...
                DefinitionType, ...
                string(VariableMap), ...
                NumPeak, ...
                DenPeak, ...
                NumLowWN, ...
                NumHighWN, ...
                NumCenterWN, ...
                DenLowWN, ...
                DenHighWN, ...
                DenCenterWN, ...
                Source, ...
                CreatedAt, ...
                'VariableNames', { ...
                'RecipeID', ...
                'RatioName', ...
                'Formula', ...
                'FeatureType', ...
                'DefinitionType', ...
                'VariableMap', ...
                'NumPeak', ...
                'DenPeak', ...
                'NumLowWN', ...
                'NumHighWN', ...
                'NumCenterWN', ...
                'DenLowWN', ...
                'DenHighWN', ...
                'DenCenterWN', ...
                'Source', ...
                'CreatedAt'});
            
            
            ok = true;
            
        end


        function [def, ok] = ...
                promptCompositeRatioDefinition(app)
            
            def = table();
            
            ok = false;
            
            
            if isempty(app.peakLibrary) || ...
                    ~istable(app.peakLibrary)
                
                uialert( ...
                    app.UIFigure, ...
                    'Peak library is empty.', ...
                    'Composite ratio');
                
                return
                
            end
            
            
            % =============================================================
            % 1. FORMULA
            % =============================================================
            
            answer = ...
                inputdlg( ...
                { ...
                ['Formula using variables.' newline ...
                'Examples:' newline ...
                '(A+B+C+D)/E' newline ...
                '(A+B+C+D+G+R+E)/(AB+C+V)' newline newline ...
                'AB is one variable. Use A*B for multiplication.'], ...
                'Display name (optional):'}, ...
                'Composite ratio / score', ...
                [5 72; 1 72], ...
                { ...
                '(A+B+C+D)/E', ...
                ''});
            
            
            if isempty(answer)
                return
            end
            
            
            formulaText = ...
                strtrim(string(answer{1}));
            
            displayName = ...
                strtrim(string(answer{2}));
            
            
            if formulaText == ""
                return
            end
            
            
            % =============================================================
            % 2. CHARACTER SAFETY
            % =============================================================
            
            formulaChar = ...
                char(formulaText);
            
            
            if isempty(regexp( ...
                    formulaChar, ...
                    '^[A-Za-z0-9_\+\-\*\/\.\^\(\)\s]+$', ...
                    'once'))
                
                uialert( ...
                    app.UIFigure, ...
                    'Formula contains unsupported characters.', ...
                    'Composite ratio');
                
                return
                
            end
            
            
            % =============================================================
            % 3. VARIABLES
            % =============================================================
            
            vars = ...
                regexp( ...
                formulaChar, ...
                '[A-Za-z]\w*', ...
                'match');
            
            
            vars = ...
                unique( ...
                string(vars), ...
                'stable');
            
            
            if numel(vars) < 2
                
                uialert( ...
                    app.UIFigure, ...
                    'Composite ratio needs at least 2 variables.', ...
                    'Composite ratio');
                
                return
                
            end
            
            
            % =============================================================
            % 4. FEATURE TYPE
            % =============================================================
            
            featureChoices = { ...
                'Area', ...
                'Exact intensity', ...
                '1/FWHM'};
            
            
            [featureIdx, okFeature] = ...
                listdlg( ...
                'PromptString', ...
                'Feature used for every formula variable:', ...
                'SelectionMode', ...
                'single', ...
                'ListString', ...
                featureChoices, ...
                'InitialValue', ...
                1);
            
            
            if ~okFeature
                return
            end
            
            
            FeatureType = ...
                string(featureChoices{featureIdx});
            
            
            % =============================================================
            % 5. PEAK LIST
            % =============================================================
            
            T = ...
                app.peakLibrary;
            
            
            peakList = ...
                strings(height(T),1);
            
            
            for p = 1:height(T)
                
                peakList(p) = ...
                    sprintf( ...
                    'P%d | %.1f–%.1f cm^-1 | %s', ...
                    p, ...
                    T.LowWN(p), ...
                    T.HighWN(p), ...
                    string(T.Interpretation(p)));
                
            end
            
            
            % =============================================================
            % 6. VARIABLE → PEAK MAPPING
            % =============================================================
            
            selectedPeak = ...
                zeros(numel(vars),1);
            
            
            for v = 1:numel(vars)
                
                [peakIdx, accepted] = ...
                    listdlg( ...
                    'PromptString', ...
                    char( ...
                    "Select peak for variable " + ...
                    vars(v) + ...
                    ":"), ...
                    'SelectionMode', ...
                    'single', ...
                    'ListString', ...
                    cellstr(peakList), ...
                    'ListSize', ...
                    [500 330]);
                
                
                if ~accepted
                    return
                end
                
                
                selectedPeak(v) = ...
                    peakIdx;
                
            end
            
            
            % =============================================================
            % 7. ENCODE VARIABLE MAP
            % =============================================================
            
            mappingParts = ...
                strings(numel(vars),1);
            
            
            for v = 1:numel(vars)
                
                mappingParts(v) = ...
                    vars(v) + ...
                    "=P" + ...
                    string(selectedPeak(v));
                
            end
            
            
            VariableMap = ...
                strjoin( ...
                mappingParts, ...
                ";");
            
            
            % =============================================================
            % 8. NAME
            % =============================================================
            
            if displayName == ""
                
                displayName = ...
                    formulaText;
                
            end
            
            
            RecipeID = ...
                "SCORE_" + ...
                string(datetime( ...
                'now', ...
                'Format', ...
                'yyyyMMdd_HHmmss_SSS'));
            
            
            RatioName = ...
                displayName;
            
            
            Formula = ...
                formulaText;
            
            
            DefinitionType = ...
                "CompositeRatio";
            
            
            % Simple-specific fields intentionally NaN.
            NumPeak = NaN;
            DenPeak = NaN;
            
            NumLowWN = NaN;
            NumHighWN = NaN;
            NumCenterWN = NaN;
            
            DenLowWN = NaN;
            DenHighWN = NaN;
            DenCenterWN = NaN;
            
            
            Source = ...
                "Composite ratio menu";
            
            
            CreatedAt = ...
                string(datetime( ...
                'now', ...
                'Format', ...
                'yyyy-MM-dd HH:mm:ss'));
            
            
            def = table( ...
                RecipeID, ...
                RatioName, ...
                Formula, ...
                FeatureType, ...
                DefinitionType, ...
                VariableMap, ...
                NumPeak, ...
                DenPeak, ...
                NumLowWN, ...
                NumHighWN, ...
                NumCenterWN, ...
                DenLowWN, ...
                DenHighWN, ...
                DenCenterWN, ...
                Source, ...
                CreatedAt, ...
                'VariableNames', { ...
                'RecipeID', ...
                'RatioName', ...
                'Formula', ...
                'FeatureType', ...
                'DefinitionType', ...
                'VariableMap', ...
                'NumPeak', ...
                'DenPeak', ...
                'NumLowWN', ...
                'NumHighWN', ...
                'NumCenterWN', ...
                'DenLowWN', ...
                'DenHighWN', ...
                'DenCenterWN', ...
                'Source', ...
                'CreatedAt'});
            
            
            ok = true;
            
        end


        function [vars, peakIDs] = ...
                parseRatioVariableMap(app, variableMap)
            
            %#ok<INUSD>
            
            variableMap = ...
                string(variableMap);
            
            
            vars = ...
                strings(0,1);
            
            peakIDs = ...
                zeros(0,1);
            
            
            if strlength(variableMap) == 0
                return
            end
            
            
            parts = ...
                split( ...
                variableMap, ...
                ";");
            
            
            for k = 1:numel(parts)
                
                token = ...
                    strtrim(parts(k));
                
                
                match = ...
                    regexp( ...
                    char(token), ...
                    '^([A-Za-z]\w*)\s*=\s*P(\d+)$', ...
                    'tokens', ...
                    'once');
                
                
                if isempty(match)
                    
                    error( ...
                        'Invalid variable mapping: %s', ...
                        token);
                    
                end
                
                
                vars(end+1,1) = ...
                    string(match{1}); %#ok<AGROW>
                
                
                peakIDs(end+1,1) = ...
                    str2double(match{2}); %#ok<AGROW>
                
            end
            
            
            if numel(unique(vars)) ~= numel(vars)
                
                error( ...
                    'Variable map contains duplicated variable names.');
                
            end
            
        end


        function result = ...
                evaluateRatioRecipeExpression( ...
                app, ...
                formulaText, ...
                vars, ...
                values)
            
            %#ok<INUSD>
            
            formulaText = ...
                string(formulaText);
            
            
            vars = ...
                string(vars(:));
            
            
            if isempty(values) || ...
                    numel(values) ~= numel(vars)
                
                error( ...
                    'Formula variable/value count mismatch.');
                
            end
            
            
            expr = ...
                char(formulaText);
            
            
            % =============================================================
            % 1. SAFE CHARACTER SET
            % =============================================================
            
            if isempty(regexp( ...
                    expr, ...
                    '^[A-Za-z0-9_\+\-\*\/\.\^\(\)\s]+$', ...
                    'once'))
                
                error( ...
                    'Formula contains unsupported characters.');
                
            end
            
            
            % =============================================================
            % 2. EVERY WORD MUST BE A DECLARED VARIABLE
            % =============================================================
            
            formulaVars = ...
                regexp( ...
                expr, ...
                '[A-Za-z]\w*', ...
                'match');
            
            
            formulaVars = ...
                unique( ...
                string(formulaVars), ...
                'stable');
            
            
            unknownVars = ...
                setdiff( ...
                formulaVars, ...
                vars);
            
            
            if ~isempty(unknownVars)
                
                error( ...
                    'Unknown formula variable(s): %s', ...
                    strjoin(unknownVars,", "));
                
            end
            
            
            % =============================================================
            % 3. ALL VALUES MUST HAVE SAME SHAPE
            % =============================================================
            
            baseSize = ...
                size(values{1});
            
            
            for k = 1:numel(values)
                
                values{k} = ...
                    double(values{k});
                
                
                if ~isequal( ...
                        size(values{k}), ...
                        baseSize)
                    
                    error( ...
                        'Formula variables do not have matching dimensions.');
                    
                end
                
            end
            
            
            % =============================================================
            % 4. ELEMENT-WISE OPERATORS
            % =============================================================
            
            expr = ...
                regexprep( ...
                expr, ...
                '(?<!\.)\*', ...
                '.*');
            
            
            expr = ...
                regexprep( ...
                expr, ...
                '(?<!\.)/', ...
                './');
            
            
            expr = ...
                regexprep( ...
                expr, ...
                '(?<!\.)\^', ...
                '.^');
            
            
            % =============================================================
            % 5. REPLACE VARIABLE TOKENS
            % =============================================================
            
            % Longest variable names first:
            % AB before A.
            [~, order] = ...
                sort( ...
                strlength(vars), ...
                'descend');
            
            
            for q = 1:numel(order)
                
                k = ...
                    order(q);
                
                
                variableName = ...
                    char(vars(k));
                
                
                expr = ...
                    regexprep( ...
                    expr, ...
                    [ ...
                    '(?<![A-Za-z0-9_])' ...
                    regexptranslate('escape',variableName) ...
                    '(?![A-Za-z0-9_])' ...
                    ], ...
                    sprintf( ...
                    'values{%d}', ...
                    k));
                
            end
            
            
            % =============================================================
            % 6. EVALUATE RESTRICTED EXPRESSION
            % =============================================================
            
            try
                
                result = ...
                    eval(expr);
                
            catch ME
                
                error( ...
                    'Cannot evaluate formula "%s": %s', ...
                    formulaText, ...
                    ME.message);
                
            end
            
            
            % =============================================================
            % 7. REQUIRE SAME SHAPE
            % =============================================================
            
            if ~isequal( ...
                    size(result), ...
                    baseSize)
                
                error( ...
                    'Formula result has an invalid dimension.');
                
            end
            
            
            result = ...
                double(result);
            
            
            result(~isfinite(result)) = ...
                NaN;
            
        end


        function ensureSpatialOptimizeHistorySchema(app)
            
            % =============================================================
            % ENSURESPATIALOPTIMIZEHISTORYSCHEMA
            %
            % Migrates old Optimize-scan history to the current schema.
            %
            % OLD:
            %   DisplayMode
            %   MediumThreshold
            %   HighThreshold
            %
            % NEW:
            %   SelectionMode
            %   PercentileLow / High
            %   ValueThresholdLow / High
            %
            % No map/cube data is created.
            % =============================================================
            
            
            requiredFields = { ...
                'OverlayID', ...
                'PanelID', ...
                'DatasetID', ...
                'DatasetName', ...
                'MapType', ...
                'SelectionMode', ...
                'PercentileLow', ...
                'PercentileHigh', ...
                'ValueThresholdLow', ...
                'ValueThresholdHigh', ...
                'Opacity', ...
                'CreatedAt', ...
                'User'};
            
            
            emptyTemplate = struct( ...
                'OverlayID', {}, ...
                'PanelID', {}, ...
                'DatasetID', {}, ...
                'DatasetName', {}, ...
                'MapType', {}, ...
                'SelectionMode', {}, ...
                'PercentileLow', {}, ...
                'PercentileHigh', {}, ...
                'ValueThresholdLow', {}, ...
                'ValueThresholdHigh', {}, ...
                'Opacity', {}, ...
                'CreatedAt', {}, ...
                'User', {});
            
            
            % =============================================================
            % 1. EMPTY / INVALID HISTORY
            % =============================================================
            
            if isempty(app.spatialOptimizeHistory)
                
                app.spatialOptimizeHistory = ...
                    emptyTemplate;
                
                return
            end
            
            
            if ~isstruct(app.spatialOptimizeHistory)
                
                app.spatialOptimizeHistory = ...
                    emptyTemplate;
                
                return
            end
            
            
            oldHistory = ...
                app.spatialOptimizeHistory;
            
            
            % =============================================================
            % 2. ALREADY CURRENT SCHEMA
            % =============================================================
            
            currentFields = ...
                fieldnames(oldHistory);
            
            
            if numel(currentFields) == numel(requiredFields) && ...
                    all(ismember(requiredFields,currentFields))
                
                return
            end
            
            
            % =============================================================
            % 3. MIGRATE ENTRY BY ENTRY
            % =============================================================
            
            newHistory = ...
                emptyTemplate;
            
            
            for k = 1:numel(oldHistory)
                
                old = ...
                    oldHistory(k);
                
                
                entry = struct();
                
                % ---------------------------------------------------------
                % ID
                % ---------------------------------------------------------
                
                if isfield(old,'OverlayID')
                    
                    entry.OverlayID = ...
                        string(old.OverlayID);
                    
                else
                    
                    entry.OverlayID = ...
                        "OVERLAY_MIGRATED_" + ...
                        string(k);
                    
                end
                
                
                % ---------------------------------------------------------
                % PANEL
                % ---------------------------------------------------------
                
                if isfield(old,'PanelID')
                    
                    entry.PanelID = ...
                        old.PanelID;
                    
                else
                    
                    entry.PanelID = ...
                        NaN;
                    
                end
                
                
                % ---------------------------------------------------------
                % DATASET
                % ---------------------------------------------------------
                
                if isfield(old,'DatasetID')
                    
                    entry.DatasetID = ...
                        string(old.DatasetID);
                    
                else
                    
                    entry.DatasetID = "";
                end
                
                
                if isfield(old,'DatasetName')
                    
                    entry.DatasetName = ...
                        string(old.DatasetName);
                    
                else
                    
                    entry.DatasetName = "";
                end
                
                
                % ---------------------------------------------------------
                % MAP TYPE
                % ---------------------------------------------------------
                
                if isfield(old,'MapType')
                    
                    entry.MapType = ...
                        string(old.MapType);
                    
                else
                    
                    entry.MapType = "";
                end
                
                
                % ---------------------------------------------------------
                % SELECTION MODE
                % ---------------------------------------------------------
                
                if isfield(old,'SelectionMode')
                    
                    entry.SelectionMode = ...
                        string(old.SelectionMode);
                    
                    
                elseif isfield(old,'DisplayMode')
                    
                    entry.SelectionMode = ...
                        string(old.DisplayMode);
                    
                    
                else
                    
                    entry.SelectionMode = ...
                        "Migrated legacy overlay";
                    
                end
                
                
                % ---------------------------------------------------------
                % PERCENTILE RANGE
                % ---------------------------------------------------------
                
                entry.PercentileLow = NaN;
                entry.PercentileHigh = NaN;
                
                
                % Infer old modes where possible.
                if isfield(old,'DisplayMode')
                    
                    oldMode = ...
                        string(old.DisplayMode);
                    
                    
                    switch oldMode
                        
                        case "High only"
                            
                            entry.PercentileLow = 90;
                            entry.PercentileHigh = 100;
                            
                            
                        case "High + Medium"
                            
                            entry.PercentileLow = 70;
                            entry.PercentileHigh = 100;
                            
                            
                        case "All"
                            
                            entry.PercentileLow = 0;
                            entry.PercentileHigh = 100;
                            
                    end
                    
                end
                
                
                if isfield(old,'PercentileLow')
                    
                    entry.PercentileLow = ...
                        old.PercentileLow;
                    
                end
                
                
                if isfield(old,'PercentileHigh')
                    
                    entry.PercentileHigh = ...
                        old.PercentileHigh;
                    
                end
                
                
                % ---------------------------------------------------------
                % ACTUAL VALUE THRESHOLDS
                % ---------------------------------------------------------
                
                entry.ValueThresholdLow = NaN;
                entry.ValueThresholdHigh = NaN;
                
                
                if isfield(old,'ValueThresholdLow')
                    
                    entry.ValueThresholdLow = ...
                        old.ValueThresholdLow;
                    
                    
                elseif isfield(old,'MediumThreshold')
                    
                    entry.ValueThresholdLow = ...
                        old.MediumThreshold;
                    
                end
                
                
                if isfield(old,'ValueThresholdHigh')
                    
                    entry.ValueThresholdHigh = ...
                        old.ValueThresholdHigh;
                    
                    
                elseif isfield(old,'HighThreshold')
                    
                    entry.ValueThresholdHigh = ...
                        old.HighThreshold;
                    
                end
                
                
                % ---------------------------------------------------------
                % OPACITY
                % ---------------------------------------------------------
                
                if isfield(old,'Opacity')
                    
                    entry.Opacity = ...
                        old.Opacity;
                    
                else
                    
                    entry.Opacity = ...
                        0.72;
                    
                end
                
                
                % ---------------------------------------------------------
                % TIME
                % ---------------------------------------------------------
                
                if isfield(old,'CreatedAt')
                    
                    entry.CreatedAt = ...
                        string(old.CreatedAt);
                    
                else
                    
                    entry.CreatedAt = ...
                        string(datetime( ...
                        'now', ...
                        'Format', ...
                        'yyyy-MM-dd HH:mm:ss'));
                    
                end
                
                
                % ---------------------------------------------------------
                % USER
                % ---------------------------------------------------------
                
                if isfield(old,'User')
                    
                    entry.User = ...
                        string(old.User);
                    
                else
                    
                    entry.User = ...
                        app.homeUser;
                    
                end
                
                
                % ---------------------------------------------------------
                % APPEND
                % ---------------------------------------------------------
                
                newHistory(end+1) = ...
                    entry; %#ok<AGROW>
                
            end
            
            
            % =============================================================
            % 4. REPLACE WITH CURRENT SCHEMA
            % =============================================================
            
            app.spatialOptimizeHistory = ...
                newHistory;
            
        end
        
        
        function y = ...
                computeRatioDefinitionVector( ...
                app, ...
                def, ...
                spectralX, ...
                wn)
            
            % =============================================================
            % COMPUTE RATIO / SCORE VECTOR
            %
            % Shared by PCA ratio and other single-spectrum analyses.
            % =============================================================
            
            
            def = ...
                app.normalizeRatioDefinitionRow(def);
            
            
            spectralX = ...
                double(spectralX);
            
            
            wn = ...
                double(wn(:));
            
            
            % =============================================================
            % 1. CANONICAL PEAK LIBRARY
            % =============================================================
            
            peakLibrary = ...
                app.getCanonicalPeakLibrary();
            
            
            if isempty(peakLibrary)
                
                error( ...
                    'No valid peak library is available.');
                
            end
            
            
            % =============================================================
            % 2. VARIABLE MAP
            % =============================================================
            
            [vars,peakIDs] = ...
                app.parseRatioVariableMap( ...
                def.VariableMap);
            
            
            if isempty(vars)
                
                error( ...
                    'Ratio / score recipe contains no variable mapping.');
                
            end
            
            
            featureValues = ...
                cell(numel(vars),1);
            
            
            % =============================================================
            % 3. COMPUTE EACH VARIABLE
            % =============================================================
            
            for k = 1:numel(vars)
                
                peakID = ...
                    round(double(peakIDs(k)));
                
                
                if ~isfinite(peakID) || ...
                        peakID < 1 || ...
                        peakID > height(peakLibrary)
                    
                    error( ...
                        'Recipe variable %s refers to unavailable peak P%d.', ...
                        vars(k), ...
                        peakID);
                    
                end
                
                
                peak = ...
                    peakLibrary(peakID,:);
                
                
                featureValues{k} = ...
                    app.computeRatioPeakFeature( ...
                    spectralX, ...
                    wn, ...
                    double(peak.LowWN), ...
                    double(peak.HighWN), ...
                    double(peak.CenterWN), ...
                    def.FeatureType);
                
            end
            
            
            % =============================================================
            % 4. EVALUATE FORMULA
            % =============================================================
            
            y = ...
                app.evaluateRatioRecipeExpression( ...
                def.Formula, ...
                vars, ...
                featureValues);
            
            
            y = ...
                double(y(:));
            
        end


        function [def, ok] = ...
                promptNewRatioDefinition(app)
            
            def = table();
            
            ok = false;
            
            
            choices = { ...
                'Simple ratio — A/B', ...
                'Composite ratio / score formula'};
            
            
            [sel, accepted] = ...
                listdlg( ...
                'PromptString', ...
                'Create new ratio definition:', ...
                'SelectionMode', ...
                'single', ...
                'ListString', ...
                choices, ...
                'InitialValue', ...
                1, ...
                'ListSize', ...
                [390 130]);
            
            
            if ~accepted || isempty(sel)
                return
            end
            
            
            switch sel
                
                case 1
                    
                    [def, ok] = ...
                        app.promptSimpleRatioDefinition();
                    
                    
                case 2
                    
                    [def, ok] = ...
                        app.promptCompositeRatioDefinition();
                    
            end
            
        end
        
        
        function [added, messageText] = ...
                addRatioDefinitionInternal(app, def)
            
            app.ensureRatioDefinitionSchema();
            
            
            added = false;
            
            messageText = "";
            
            
            try
                
                def = ...
                    app.normalizeRatioDefinitionRow(def);
                
            catch ME
                
                messageText = ...
                    "Invalid ratio definition: " + ...
                    string(ME.message);
                
                return
                
            end
            
            
            newKey = ...
                app.makeRatioDefinitionKey(def);
            
            
            existingKeys = ...
                strings( ...
                height(app.ratioDefinitions), ...
                1);
            
            
            for k = 1:height(app.ratioDefinitions)
                
                existingKeys(k) = ...
                    app.makeRatioDefinitionKey( ...
                    app.ratioDefinitions(k,:));
                
            end
            
            
            duplicateRow = ...
                find( ...
                existingKeys == newKey, ...
                1, ...
                'first');
            
            
            if ~isempty(duplicateRow)
                
                messageText = ...
                    "This ratio / score recipe is already saved.";
                
                return
                
            end
            
            
            app.ratioDefinitions = ...
                [ ...
                app.ratioDefinitions; ...
                def ...
                ];
            
            
            added = true;
            
            
            messageText = ...
                "Saved: " + ...
                string(def.RatioName);
            
        end


        function T = emptyInterpretationRegistry(app)
            
            
            % =========================================================
            % ONE ROW = ONE COMMITTED INTERPRETATION EVIDENCE PACKAGE
            %
            % IMPORTANT:
            % Keep this table lightweight.
            % =========================================================
            
            T = table( ...
                strings(0,1), ...   % EvidenceID
                strings(0,1), ...   % Module
                strings(0,1), ...   % ComparisonID
                strings(0,1), ...   % GroupA
                strings(0,1), ...   % GroupB
                strings(0,1), ...   % ResultType
                strings(0,1), ...   % ResultName
                strings(0,1), ...   % Direction
                strings(0,1), ...   % Strength
                NaN(0,1), ...       % PValue
                NaN(0,1), ...       % QValue
                NaN(0,1), ...       % EffectSize
                strings(0,1), ...   % EffectName
                NaN(0,1), ...       % NumeratorPeak
                NaN(0,1), ...       % DenominatorPeak
                strings(0,1), ...   % NumeratorDirection
                strings(0,1), ...   % DenominatorDirection
                strings(0,1), ...   % Driver
                strings(0,1), ...   % MeanSupport
                strings(0,1), ...   % PCASupport
                strings(0,1), ...   % BiochemicalSummary
                strings(0,1), ...   % ReferenceText
                strings(0,1), ...   % FeatureType
                strings(0,1), ...   % SourceData
                strings(0,1), ...   % Status
                strings(0,1), ...   % CreatedAt
                strings(0,1), ...   % UpdatedAt
                'VariableNames', { ...
                'EvidenceID', ...
                'Module', ...
                'ComparisonID', ...
                'GroupA', ...
                'GroupB', ...
                'ResultType', ...
                'ResultName', ...
                'Direction', ...
                'Strength', ...
                'PValue', ...
                'QValue', ...
                'EffectSize', ...
                'EffectName', ...
                'NumeratorPeak', ...
                'DenominatorPeak', ...
                'NumeratorDirection', ...
                'DenominatorDirection', ...
                'Driver', ...
                'MeanSupport', ...
                'PCASupport', ...
                'BiochemicalSummary', ...
                'ReferenceText', ...
                'FeatureType', ...
                'SourceData', ...
                'Status', ...
                'CreatedAt', ...
                'UpdatedAt'});
            
        end
        
        
        function ensureInterpretationRegistrySchema(app)
            
            canonical = ...
                app.emptyInterpretationRegistry();
            
            % =========================================================
            % EMPTY
            % =========================================================
            if isempty(app.interpretationRegistry)
                
                app.interpretationRegistry = ...
                    canonical;
                
                return
            end
            
            if ~istable(app.interpretationRegistry)
                
                app.interpretationRegistry = ...
                    canonical;
                
                return
            end
            
            % =========================================================
            % ADD MISSING COLUMNS
            %
            % Allows old metadata/session files to remain readable.
            % =========================================================
            requiredVars = ...
                string(canonical.Properties.VariableNames);
            
            currentVars = ...
                string(app.interpretationRegistry.Properties.VariableNames);
            
            for k = 1:numel(requiredVars)
                
                varName = ...
                    requiredVars(k);
                
                if ismember(varName, currentVars)
                    continue
                end
                
                template = ...
                    canonical.(char(varName));
                
                if isnumeric(template)
                    
                    app.interpretationRegistry.(char(varName)) = ...
                        NaN(height(app.interpretationRegistry),1);
                    
                else
                    
                    app.interpretationRegistry.(char(varName)) = ...
                        strings(height(app.interpretationRegistry),1);
                    
                end
                
            end
            
            % =========================================================
            % CANONICAL COLUMN ORDER
            % =========================================================
            app.interpretationRegistry = ...
                app.interpretationRegistry(:, ...
                cellstr(requiredVars));
        end
        
        
        function comparisonID = ...
                makeComparisonID(app, groupA, groupB)
            
            groupA = ...
                strtrim(string(groupA));
            
            groupB = ...
                strtrim(string(groupB));
            
            safeA = ...
                regexprep(groupA, '[^A-Za-z0-9]+', '_');
            
            safeB = ...
                regexprep(groupB, '[^A-Za-z0-9]+', '_');
            
            safeA = ...
                regexprep(safeA, '^_+|_+$', '');
            
            safeB = ...
                regexprep(safeB, '^_+|_+$', '');
            
            comparisonID = ...
                safeA + "__VS__" + safeB;
            
        end
        
        
        function evidenceID = ...
                makeRatioEvidenceID( ...
                app, ...
                comparisonID, ...
                ratioName)
            
            
            comparisonID = ...
                string(comparisonID);
            
            ratioName = ...
                string(ratioName);
            
            safeRatio = ...
                regexprep( ...
                ratioName, ...
                '[^A-Za-z0-9]+', ...
                '_');
            
            safeRatio = ...
                regexprep( ...
                safeRatio, ...
                '^_+|_+$', ...
                '');
            
            evidenceID = ...
                "RATIO__" + ...
                comparisonID + ...
                "__" + ...
                safeRatio;
            
        end


        function commitInterpretationEvidence(app, evidenceRow)
            
            % =========================================================
            % VALIDATE
            % =========================================================
            app.ensureInterpretationRegistrySchema();
            
            if isempty(evidenceRow) || ...
                    ~istable(evidenceRow) || ...
                    height(evidenceRow) ~= 1
                
                error( ...
                    ['Interpretation evidence must be a ', ...
                    'single-row table.']);
                
            end
            
            if ~ismember( ...
                    'EvidenceID', ...
                    evidenceRow.Properties.VariableNames)
                
                error( ...
                    'Evidence row does not contain EvidenceID.');
                
            end
            
            evidenceID = ...
                strtrim(string(evidenceRow.EvidenceID(1)));
            
            if evidenceID == ""
                
                error( ...
                    'EvidenceID cannot be empty.');
                
            end
            
            % =========================================================
            % NORMALIZE AGAINST CANONICAL SCHEMA
            % =========================================================
            canonical = ...
                app.emptyInterpretationRegistry();
            
            canonicalVars = ...
                string(canonical.Properties.VariableNames);
            
            rowVars = ...
                string(evidenceRow.Properties.VariableNames);
            
            
            % The previous line cannot operate on a 0-row table in all MATLAB
            % releases, so build by appending first.
            normalizedRow = ...
                app.makeEmptyInterpretationRow();
            
            for k = 1:numel(canonicalVars)
                
                v = canonicalVars(k);
                
                if ismember(v, rowVars)
                    
                    normalizedRow.(char(v))(1) = ...
                        evidenceRow.(char(v))(1);
                    
                end
                
            end
            
            % =========================================================
            % TIMESTAMPS
            % =========================================================
            nowText = ...
                string(datetime( ...
                'now', ...
                'Format', ...
                'yyyy-MM-dd HH:mm:ss'));
            
            idxExisting = ...
                find( ...
                string(app.interpretationRegistry.EvidenceID) == ...
                evidenceID, ...
                1, ...
                'first');
            
            if isempty(idxExisting)
                
                normalizedRow.CreatedAt(1) = ...
                    nowText;
                
                normalizedRow.UpdatedAt(1) = ...
                    nowText;
                
                app.interpretationRegistry = ...
                    [ ...
                    app.interpretationRegistry; ...
                    normalizedRow];
                
            else
                
                % Preserve original CreatedAt.
                if strlength( ...
                        string(app.interpretationRegistry.CreatedAt( ...
                        idxExisting))) > 0
                    
                    normalizedRow.CreatedAt(1) = ...
                        string( ...
                        app.interpretationRegistry.CreatedAt( ...
                        idxExisting));
                    
                else
                    
                    normalizedRow.CreatedAt(1) = ...
                        nowText;
                    
                end
                
                normalizedRow.UpdatedAt(1) = ...
                    nowText;
                
                app.interpretationRegistry( ...
                    idxExisting,:) = ...
                    normalizedRow;
                
            end
            
            % =========================================================
            % SUMMARY NEEDS REFRESH
            % =========================================================
            app.summaryDirty = true;
            
            % =========================================================
            % METADATA SAVE
            % =========================================================
            app.saveAnalysisMetadata(false);
            
        end


        function T = makeEmptyInterpretationRow(app)
            
            T = table( ...
                "", ...        % EvidenceID
                "", ...        % Module
                "", ...        % ComparisonID
                "", ...        % GroupA
                "", ...        % GroupB
                "", ...        % ResultType
                "", ...        % ResultName
                "", ...        % Direction
                "", ...        % Strength
                NaN, ...       % PValue
                NaN, ...       % QValue
                NaN, ...       % EffectSize
                "", ...        % EffectName
                NaN, ...       % NumeratorPeak
                NaN, ...       % DenominatorPeak
                "", ...        % NumeratorDirection
                "", ...        % DenominatorDirection
                "", ...        % Driver
                "", ...        % MeanSupport
                "", ...        % PCASupport
                "", ...        % BiochemicalSummary
                "", ...        % ReferenceText
                "", ...        % FeatureType
                "", ...        % SourceData
                "Committed", ... % Status
                "", ...        % CreatedAt
                "", ...        % UpdatedAt
                'VariableNames', { ...
                'EvidenceID', ...
                'Module', ...
                'ComparisonID', ...
                'GroupA', ...
                'GroupB', ...
                'ResultType', ...
                'ResultName', ...
                'Direction', ...
                'Strength', ...
                'PValue', ...
                'QValue', ...
                'EffectSize', ...
                'EffectName', ...
                'NumeratorPeak', ...
                'DenominatorPeak', ...
                'NumeratorDirection', ...
                'DenominatorDirection', ...
                'Driver', ...
                'MeanSupport', ...
                'PCASupport', ...
                'BiochemicalSummary', ...
                'ReferenceText', ...
                'FeatureType', ...
                'SourceData', ...
                'Status', ...
                'CreatedAt', ...
                'UpdatedAt'});
            
        end


        function initializeSummaryResultsUI(app)
            
            % =========================================================
            % TEXT AREA
            % =========================================================
            app.TextArea_8.Editable = ...
                'off';
            
            app.TextArea_8.Value = { ...
                'SUMMARIZE RESULTS'; ...
                ''; ...
                'No committed interpretation is available.'};
            
            % =========================================================
            % DETAIL MODE
            % =========================================================
            app.DropDown.Items = { ...
                'Spectra comparison', ...
                'Ratio', ...
                'PCA', ...
                'Classification'};
            
            app.DropDown.Value = ...
                'Spectra comparison';
            
            % =========================================================
            % AXIS
            % =========================================================
            cla(app.UIAxes11, 'reset');
            
            app.UIAxes11.Box = ...
                'on';
            
            grid(app.UIAxes11, 'off');
            
            title( ...
                app.UIAxes11, ...
                'Integrated evidence summary');
            
            % =========================================================
            % TREE
            % =========================================================
            delete(app.Tree7.Children);
            
            root = ...
                uitreenode( ...
                app.Tree7, ...
                'Text', ...
                'No summarized evidence', ...
                'NodeData', ...
                struct( ...
                'Type', "root"));
            
            expand(root);
            
            % =========================================================
            % COMPARISON DROPDOWN
            % =========================================================
            app.refreshSummaryComparisonDropdown();
            
        end
        
        
        function refreshSummaryComparisonDropdown(app)
            
            app.ensureInterpretationRegistrySchema();
            
            oldValue = ...
                string(app.ComparativesubjectDropDown.Value);
            
            if isempty(app.interpretationRegistry)
                
                app.ComparativesubjectDropDown.Items = ...
                    {''};
                
                app.ComparativesubjectDropDown.Value = ...
                    '';
                
                return
            end
            
            T = ...
                app.interpretationRegistry;
            
            valid = ...
                string(T.Status) == "Committed";
            
            T = ...
                T(valid,:);
            
            if isempty(T)
                
                app.ComparativesubjectDropDown.Items = ...
                    {''};
                
                app.ComparativesubjectDropDown.Value = ...
                    '';
                
                return
            end
            
            labels = ...
                string(T.GroupA) + ...
                " vs " + ...
                string(T.GroupB);
            
            labels = ...
                unique(labels, 'stable');
            
            items = ...
                [""; labels(:)];
            
            app.ComparativesubjectDropDown.Items = ...
                cellstr(items);
            
            if oldValue ~= "" && ...
                    any(items == oldValue)
                
                app.ComparativesubjectDropDown.Value = ...
                    char(oldValue);
                
            else
                
                app.ComparativesubjectDropDown.Value = ...
                    char(labels(1));
                
            end
            
        end


        function rebuildSummaryTree(app)
            
            app.ensureInterpretationRegistrySchema();
            
            delete(app.Tree7.Children);
            
            comparisonText = ...
                strtrim( ...
                string( ...
                app.ComparativesubjectDropDown.Value));
            
            % =========================================================
            % NOTHING SELECTED
            % =========================================================
            if comparisonText == ""
                
                root = ...
                    uitreenode( ...
                    app.Tree7, ...
                    'Text', ...
                    'No comparison selected');
                
                expand(root);
                
                return
            end
            
            % =========================================================
            % FILTER REGISTRY
            % =========================================================
            parts = ...
                split(comparisonText, " vs ");
            
            if numel(parts) ~= 2
                return
            end
            
            groupA = ...
                strtrim(parts(1));
            
            groupB = ...
                strtrim(parts(2));
            
            T = ...
                app.interpretationRegistry;
            
            keep = ...
                string(T.GroupA) == groupA & ...
                string(T.GroupB) == groupB & ...
                string(T.Status) == "Committed";
            
            T = ...
                T(keep,:);
            
            root = ...
                uitreenode( ...
                app.Tree7, ...
                'Text', ...
                char(comparisonText), ...
                'NodeData', ...
                struct( ...
                'Type', "comparison", ...
                'GroupA', groupA, ...
                'GroupB', groupB));
            
            if isempty(T)
                
                root.Text = ...
                    char(comparisonText + ...
                    " — no committed evidence");
                
                expand(root);
                
                return
            end
            
            % =========================================================
            % RATIO EVIDENCE
            % =========================================================
            ratioMask = ...
                string(T.Module) == "Ratio";
            
            TR = ...
                T(ratioMask,:);
            
            ratioRoot = ...
                uitreenode( ...
                root, ...
                'Text', ...
                sprintf( ...
                'Ratio evidence (%d)', ...
                height(TR)), ...
                'NodeData', ...
                struct('Type',"ratio_root"));
            
            directions = [ ...
                "A > B", ...
                "A < B", ...
                "A ~ B"];
            
            for d = 1:numel(directions)
                
                dirText = ...
                    directions(d);
                
                maskDir = ...
                    string(TR.Direction) == ...
                    dirText;
                
                Td = ...
                    TR(maskDir,:);
                
                nodeDir = ...
                    uitreenode( ...
                    ratioRoot, ...
                    'Text', ...
                    sprintf( ...
                    '%s (%d)', ...
                    char(dirText), ...
                    height(Td)), ...
                    'NodeData', ...
                    struct( ...
                    'Type',"direction", ...
                    'Direction',dirText));
                
                for r = 1:height(Td)
                    
                    label = ...
                        string(Td.ResultName(r));
                    
                    strength = ...
                        string(Td.Strength(r));
                    
                    if strength ~= ""
                        
                        label = ...
                            label + ...
                            " — " + ...
                            strength;
                        
                    end
                    
                    node = ...
                        uitreenode( ...
                        nodeDir, ...
                        'Text', ...
                        char(label));
                    
                    node.NodeData = ...
                        struct( ...
                        'Type', ...
                        "evidence", ...
                        'EvidenceID', ...
                        string(Td.EvidenceID(r)));
                    
                end
                
            end
            
            % =========================================================
            % DRIVER SUMMARY
            % =========================================================
            interpretedDriver = ...
                strtrim(string(TR.Driver));
            
            hasDriver = ...
                interpretedDriver ~= "";
            
            driverRoot = ...
                uitreenode( ...
                root, ...
                'Text', ...
                sprintf( ...
                'Interpretation drivers (%d)', ...
                nnz(hasDriver)), ...
                'NodeData', ...
                struct('Type',"driver_root"));
            
            driverTypes = [ ...
                "Numerator-driven", ...
                "Denominator-driven", ...
                "Both changed", ...
                "Relative change", ...
                "Ratio-only separation"];
            
            for d = 1:numel(driverTypes)
                
                driverText = ...
                    driverTypes(d);
                
                maskDriver = ...
                    string(TR.Driver) == ...
                    driverText;
                
                countDriver = ...
                    nnz(maskDriver);
                
                uitreenode( ...
                    driverRoot, ...
                    'Text', ...
                    sprintf( ...
                    '%s (%d)', ...
                    char(driverText), ...
                    countDriver), ...
                    'NodeData', ...
                    struct( ...
                    'Type',"driver", ...
                    'Driver',driverText));
                
            end
            
            % =========================================================
            % SUPPORT SUMMARY
            % =========================================================
            supportRoot = ...
                uitreenode( ...
                root, ...
                'Text', ...
                'Supporting evidence', ...
                'NodeData', ...
                struct('Type',"support_root"));
            
            meanCount = ...
                nnz(strlength(strtrim(string(TR.MeanSupport))) > 0);
            
            pcaCount = ...
                nnz(strlength(strtrim(string(TR.PCASupport))) > 0);
            
            uitreenode( ...
                supportRoot, ...
                'Text', ...
                sprintf( ...
                'Mean spectra support (%d)', ...
                meanCount));
            
            uitreenode( ...
                supportRoot, ...
                'Text', ...
                sprintf( ...
                'PCA support (%d)', ...
                pcaCount));
            
            expand(root);
            expand(ratioRoot);
            
        end


        function row = getSelectedSummaryEvidence(app)
            
            row = table();
            
            selectedNodes = ...
                app.Tree7.SelectedNodes;
            
            if isempty(selectedNodes)
                return
            end
            
            nd = ...
                selectedNodes(1).NodeData;
            
            if isempty(nd) || ...
                    ~isstruct(nd) || ...
                    ~isfield(nd,'Type') || ...
                    string(nd.Type) ~= "evidence"
                
                return
            end
            
            evidenceID = ...
                string(nd.EvidenceID);
            
            idx = ...
                find( ...
                string(app.interpretationRegistry.EvidenceID) == ...
                evidenceID, ...
                1);
            
            if isempty(idx)
                return
            end
            
            row = ...
                app.interpretationRegistry(idx,:);
            
        end


        function showSummaryEvidenceText(app, row)
            
            if isempty(row)
                
                app.TextArea_8.Value = { ...
                    'No evidence selected.'};
                
                return
            end
            
            lines = ...
                strings(0,1);
            
            lines(end+1) = ...
                string(row.ResultName);
            
            lines(end+1) = ...
                string(row.GroupA) + ...
                " vs " + ...
                string(row.GroupB);
            
            lines(end+1) = "";
            
            if string(row.Direction) ~= ""
                
                lines(end+1) = ...
                    "Direction: " + ...
                    string(row.Direction);
                
            end
            
            if string(row.Strength) ~= ""
                
                lines(end+1) = ...
                    "Strength: " + ...
                    string(row.Strength);
                
            end
            
            if isfinite(row.PValue)
                
                lines(end+1) = ...
                    sprintf( ...
                    'p = %.4g', ...
                    row.PValue);
                
            end
            
            if isfinite(row.QValue)
                
                lines(end+1) = ...
                    sprintf( ...
                    'q = %.4g', ...
                    row.QValue);
                
            end
            
            if isfinite(row.EffectSize)
                
                if string(row.EffectName) ~= ""
                    
                    lines(end+1) = ...
                        sprintf( ...
                        '%s = %.3f', ...
                        char(row.EffectName), ...
                        row.EffectSize);
                    
                else
                    
                    lines(end+1) = ...
                        sprintf( ...
                        'Effect = %.3f', ...
                        row.EffectSize);
                    
                end
                
            end
            
            if string(row.Driver) ~= ""
                
                lines(end+1) = "";
                lines(end+1) = ...
                    "Driver: " + ...
                    string(row.Driver);
                
            end
            
            if string(row.NumeratorDirection) ~= ""
                
                lines(end+1) = ...
                    "Numerator: " + ...
                    string(row.NumeratorDirection);
                
            end
            
            if string(row.DenominatorDirection) ~= ""
                
                lines(end+1) = ...
                    "Denominator: " + ...
                    string(row.DenominatorDirection);
                
            end
            
            if string(row.MeanSupport) ~= ""
                
                lines(end+1) = "";
                lines(end+1) = ...
                    "Mean spectra: " + ...
                    string(row.MeanSupport);
                
            end
            
            if string(row.PCASupport) ~= ""
                
                lines(end+1) = ...
                    "PCA: " + ...
                    string(row.PCASupport);
                
            end
            
            if string(row.BiochemicalSummary) ~= ""
                
                lines(end+1) = "";
                lines(end+1) = ...
                    "Biochemical interpretation:";
                
                lines(end+1) = ...
                    string(row.BiochemicalSummary);
                
            end
            
            if string(row.ReferenceText) ~= ""
                
                lines(end+1) = "";
                lines(end+1) = ...
                    "References: " + ...
                    string(row.ReferenceText);
                
            end
            
            app.TextArea_8.Value = ...
                cellstr(lines);
            
        end


        function plotSummaryOverview(app)
            
            cla(app.UIAxes11, 'reset');
            
            app.UIAxes11.Box = 'on';
            grid(app.UIAxes11, 'off');
            
            comparisonText = ...
                string(app.ComparativesubjectDropDown.Value);
            
            if comparisonText == ""
                title(app.UIAxes11, ...
                    'Integrated evidence summary');
                return
            end
            
            parts = ...
                split(comparisonText, " vs ");
            
            if numel(parts) ~= 2
                return
            end
            
            T = ...
                app.interpretationRegistry;
            
            keep = ...
                string(T.GroupA) == strtrim(parts(1)) & ...
                string(T.GroupB) == strtrim(parts(2)) & ...
                string(T.Status) == "Committed" & ...
                string(T.Module) == "Ratio";
            
            T = ...
                T(keep,:);
            
            directions = [ ...
                "A > B", ...
                "A < B", ...
                "A ~ B"];
            
            counts = ...
                zeros(1,3);
            
            for k = 1:3
                
                counts(k) = ...
                    nnz(string(T.Direction) == directions(k));
                
            end
            
            bar( ...
                app.UIAxes11, ...
                1:3, ...
                counts);
            
            app.UIAxes11.XTick = ...
                1:3;
            
            app.UIAxes11.XTickLabel = ...
                cellstr(directions);
            
            ylabel( ...
                app.UIAxes11, ...
                'Number of interpreted ratios');
            
            title( ...
                app.UIAxes11, ...
                char( ...
                "Integrated ratio evidence — " + ...
                comparisonText), ...
                'Interpreter', ...
                'none');
            
        end


        function reloadInterpretationRegistryFromMetadata(app)
            
            % =========================================================
            % Metadata is authoritative for committed summary evidence.
            %
            % This allows:
            % Ratio -> modify/save metadata
            % Summary -> Update
            %
            % without duplicating analysis matrices.
            % =========================================================
            
            if strlength(app.singleOutputSessionFolder) == 0
                return
            end
            
            metadataFile = ...
                fullfile( ...
                app.singleOutputSessionFolder, ...
                '06_Analysis_Metadata', ...
                'analysis_metadata.mat');
            
            if ~isfile(metadataFile)
                return
            end
            
            try
                
                L = ...
                    load( ...
                    metadataFile, ...
                    'M');
                
                if ~isfield(L,'M') || ...
                        ~isstruct(L.M)
                    
                    return
                end
                
                M = ...
                    L.M;
                
                if ~isfield(M,'Interpretation') || ...
                        ~isstruct(M.Interpretation) || ...
                        ~isfield(M.Interpretation,'Registry')
                    
                    return
                end
                
                incoming = ...
                    M.Interpretation.Registry;
                
                if istable(incoming)
                    
                    app.interpretationRegistry = ...
                        incoming;
                    
                    app.ensureInterpretationRegistrySchema();
                    
                end
                
            catch ME
                
                warning( ...
                    'RamanCube:SummaryMetadataReload', ...
                    ['Could not reload interpretation ', ...
                    'metadata: %s'], ...
                    ME.message);
                
            end
            
        end
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