classdef subapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        SupportPanel          matlab.ui.container.Panel
        GridLayout2           matlab.ui.container.GridLayout
        FinishButton          matlab.ui.control.Button
        NamegroupsButton_2    matlab.ui.control.Button
        SinglewindowButton    matlab.ui.control.Button
        ComparemodesButton    matlab.ui.control.Button
        RatiosettingsButton   matlab.ui.control.Button
        SpatialdistributionsettingsButton  matlab.ui.control.Button
        ExcelsupportButton    matlab.ui.control.Button
        ClusterlocatesButton  matlab.ui.control.Button
        EditableplotButton    matlab.ui.control.Button
        GridLayout5           matlab.ui.container.GridLayout
        Button_2              matlab.ui.control.Button
        Button                matlab.ui.control.Button
        Panel_2               matlab.ui.container.Panel
        GridLayout4           matlab.ui.container.GridLayout
        DropDown              matlab.ui.control.DropDown
        Image                 matlab.ui.control.Image
        Panel                 matlab.ui.container.Panel
        GridLayout3           matlab.ui.container.GridLayout
        ScoredotSlider        matlab.ui.control.Slider
        ScoredotSliderLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        ParentAnalysisApp = []
        CurrentContext string = ""
        ContextPayload = struct()
    end
    methods (Access = public)
        function receiveSupportContext(app,parentApp,context,payload)
            app.ParentAnalysisApp = parentApp;
            app.CurrentContext = upper(string(context));
            if nargin >= 4 && ~isempty(payload)
                app.ContextPayload = payload;
            else
                app.ContextPayload = struct();
            end
            app.configureSupportForContext();
            app.UIFigure.Visible = 'on';
            try
                app.UIFigure.WindowState = 'normal';
            catch
            end
            figure(app.UIFigure);
            drawnow;
        end
    end
    methods (Access = private)
        function configureSupportForContext(app)
            app.enableAllSupportControls(false);
            app.Button.Enable = 'on';
            app.Button_2.Enable = 'on';
            app.ComparemodesButton.Enable = 'on';
            app.FinishButton.Enable = 'on';
            app.EditableplotButton.Enable = 'on';
            app.SinglewindowButton.Enable = 'off';
            app.ExcelsupportButton.Enable = 'off';
            switch app.CurrentContext
                case "PCA_SCORE"
                    app.SupportPanel.Title = 'Support - PCA scores';
                    app.ScoredotSlider.Enable = 'on';
                    app.ClusterlocatesButton.Enable = 'on';
                    app.NamegroupsButton_2.Enable = 'on';
                case "PCA_LOADING"
                    app.SupportPanel.Title = 'Support - PCA loadings';
                case "RATIO"
                    app.SupportPanel.Title = 'Support - Ratio';
                    app.RatiosettingsButton.Enable = 'on';
                    app.NamegroupsButton_2.Enable = 'on';
                case "MAIN_AXIS_1"
                    app.SupportPanel.Title = 'Support - Main axis 1';
                    app.DropDown.Enable = 'on';
                    app.SpatialdistributionsettingsButton.Enable = 'on';
                    app.NamegroupsButton_2.Enable = 'on';
                case "MAIN_AXIS_2"
                    app.SupportPanel.Title = 'Support - Main axis 2';
                    app.DropDown.Enable = 'on';
                    app.SpatialdistributionsettingsButton.Enable = 'on';
                    app.NamegroupsButton_2.Enable = 'on';
                case "CLASS_SCORE"
                    app.SupportPanel.Title = 'Support - Classification scores';
                    app.ScoredotSlider.Enable = 'on';
                    app.ClusterlocatesButton.Enable = 'on';
                    app.NamegroupsButton_2.Enable = 'on';
                case "CLASS_LOADING"
                    app.SupportPanel.Title = 'Support - Classification loadings';
                otherwise
                    app.SupportPanel.Title = 'Support';
            end
        end
        function enableAllSupportControls(app,state)
            if state
                value = 'on';
            else
                value = 'off';
            end
            app.DropDown.Enable = value;
            app.ScoredotSlider.Enable = value;
            app.ComparemodesButton.Enable = value;
            app.SinglewindowButton.Enable = value;
            app.ClusterlocatesButton.Enable = value;
            app.RatiosettingsButton.Enable = value;
            app.NamegroupsButton_2.Enable = value;
            app.EditableplotButton.Enable = value;
            app.SpatialdistributionsettingsButton.Enable = value;
            app.ExcelsupportButton.Enable = value;
            app.Button.Enable = value;
            app.Button_2.Enable = value;
            app.FinishButton.Enable = value;
        end
        function sendSupportCommand(app,command,value)
            if nargin < 3
                value = [];
            end
            parentValid = false;
            try
                parentValid = ~isempty(app.ParentAnalysisApp) && ...
                    isvalid(app.ParentAnalysisApp);
            catch
                parentValid = false;
            end
            if ~parentValid
                uialert(app.UIFigure, ...
                    'The parent analysis workspace is no longer available.', ...
                    'Support');
                return
            end
            app.ParentAnalysisApp.applySupportCommand( ...
                app.CurrentContext,command,value);
        end


        function [allX,allY] = drawScoreGroupEllipses(app,ax,x,y,labels)
            allX = [];
            allY = [];
            groupNames = unique(labels,'stable');
            theta = linspace(0,2*pi,240);
            for g = 1:numel(groupNames)
                idx = app.groupMask(labels,groupNames(g));
                xg = double(x(idx));
                yg = double(y(idx));
                valid = isfinite(xg) & isfinite(yg);
                xg = xg(valid);
                yg = yg(valid);
                if numel(xg) < 3
                    continue
                end
                mu = [mean(xg),mean(yg)];
                C = cov([xg yg]);
                if any(~isfinite(C(:))) || rank(C) < 1
                    continue
                end
                [V,D] = eig(C);
                d = max(real(diag(D)),0);
                ellipse = mu(:) + 2.2 * V * diag(sqrt(d)) * [cos(theta);sin(theta)];
                ex = ellipse(1,:);
                ey = ellipse(2,:);
                plot(ax,ex,ey,'LineWidth',1.5,'HandleVisibility','off');
                allX = [allX;ex(:)]; %#ok<AGROW>
                allY = [allY;ey(:)]; %#ok<AGROW>
            end
        end
        function applyScoreLimitsWithEllipses(app,ax,x,y,ellipseX,ellipseY)
            allX = [double(x(:));double(ellipseX(:))];
            allY = [double(y(:));double(ellipseY(:))];
            validX = isfinite(allX);
            validY = isfinite(allY);
            allX = allX(validX);
            allY = allY(validY);
            if isempty(allX) || isempty(allY)
                return
            end
            xl = [min(allX) max(allX)];
            yl = [min(allY) max(allY)];
            xr = diff(xl);
            yr = diff(yl);
            if ~isfinite(xr) || xr <= 0
                xr = 1;
            end
            if ~isfinite(yr) || yr <= 0
                yr = 1;
            end
            xlim(ax,[xl(1)-0.10*xr xl(2)+0.10*xr]);
            ylim(ax,[yl(1)-0.10*yr yl(2)+0.10*yr]);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.sendSupportCommand("COLORMAP",app.DropDown.Value);
            
        end

        % Value changed function: ScoredotSlider
        function ScoredotSliderValueChanged(app, event)
            app.sendSupportCommand("SCORE_DOT",app.ScoredotSlider.Value);
            
        end

        % Button pushed function: ComparemodesButton
        function ComparemodesButtonPushed(app, event)
            app.sendSupportCommand("COMPARE_MODE",[]);
        end

        % Button pushed function: SinglewindowButton
        function SinglewindowButtonPushed(app, event)
            app.sendSupportCommand("SINGLE_WINDOW",[]);
        end

        % Button pushed function: ClusterlocatesButton
        function ClusterlocatesButtonPushed(app, event)
            app.sendSupportCommand("CLUSTER_LOCATES",[]);
        end

        % Button pushed function: RatiosettingsButton
        function RatiosettingsButtonPushed(app, event)
            app.sendSupportCommand("RATIO_SETTINGS",[]);
        end

        % Button pushed function: NamegroupsButton_2
        function NamegroupsButton_2Pushed(app, event)
            app.sendSupportCommand("NAME_GROUPS",[]);
        end

        % Button pushed function: EditableplotButton
        function EditableplotButtonPushed(app, event)
            app.sendSupportCommand("EDITABLE_PLOT",[]);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.sendSupportCommand("NEXT_CONTEXT",[]);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.sendSupportCommand("PREVIOUS_CONTEXT",[]);
        end

        % Button pushed function: SpatialdistributionsettingsButton
        function SpatialdistributionsettingsButtonPushed(app, event)
            app.sendSupportCommand("SPATIAL_SETTINGS",[]);
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            app.UIFigure.Visible = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SupportPanel = uipanel(app.GridLayout);
            app.SupportPanel.Title = 'Support ';
            app.GridLayout2 = uigridlayout(app.SupportPanel);
            app.Panel = uipanel(app.GridLayout2);
            app.GridLayout3 = uigridlayout(app.Panel);
            app.ScoredotSliderLabel = uilabel(app.GridLayout3);
            app.ScoredotSliderLabel.Text = 'Score dot';
            app.ScoredotSlider = uislider(app.GridLayout3);
            app.ScoredotSlider.Limits = [30 90];
            app.ScoredotSlider.MajorTicks = [30 50 70 90];
            app.ScoredotSlider.ValueChangedFcn = createCallbackFcn(app, @ScoredotSliderValueChanged, true);
            app.ScoredotSlider.MinorTicks = [30 50 70 90];
            app.ScoredotSlider.Value = 30;
            app.Panel_2 = uipanel(app.GridLayout2);
            app.GridLayout4 = uigridlayout(app.Panel_2);
            app.Image = uiimage(app.GridLayout4);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'RGB.png');
            app.DropDown = uidropdown(app.GridLayout4);
            app.DropDown.Items = {'hot', 'jet', 'gray', 'bone', ''};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'hot';
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.Button = uibutton(app.GridLayout5, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'go.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.GridLayout5, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'back2.png';
            app.Button_2.Text = '';
            app.EditableplotButton = uibutton(app.GridLayout2, 'push');
            app.EditableplotButton.ButtonPushedFcn = createCallbackFcn(app, @EditableplotButtonPushed, true);
            app.EditableplotButton.Text = 'Editable plot';
            app.ClusterlocatesButton = uibutton(app.GridLayout2, 'push');
            app.ClusterlocatesButton.ButtonPushedFcn = createCallbackFcn(app, @ClusterlocatesButtonPushed, true);
            app.ClusterlocatesButton.Icon = '8847040.png';
            app.ClusterlocatesButton.Text = 'Cluster locates';
            app.ExcelsupportButton = uibutton(app.GridLayout2, 'push');
            app.ExcelsupportButton.Text = 'Excel support';
            app.SpatialdistributionsettingsButton = uibutton(app.GridLayout2, 'push');
            app.SpatialdistributionsettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SpatialdistributionsettingsButtonPushed, true);
            app.SpatialdistributionsettingsButton.Text = 'Spatial distribution settings';
            app.RatiosettingsButton = uibutton(app.GridLayout2, 'push');
            app.RatiosettingsButton.ButtonPushedFcn = createCallbackFcn(app, @RatiosettingsButtonPushed, true);
            app.RatiosettingsButton.Text = 'Ratio settings';
            app.ComparemodesButton = uibutton(app.GridLayout2, 'push');
            app.ComparemodesButton.ButtonPushedFcn = createCallbackFcn(app, @ComparemodesButtonPushed, true);
            app.ComparemodesButton.Text = 'Compare modes';
            app.SinglewindowButton = uibutton(app.GridLayout2, 'push');
            app.SinglewindowButton.ButtonPushedFcn = createCallbackFcn(app, @SinglewindowButtonPushed, true);
            app.SinglewindowButton.Text = 'Single window';
            app.NamegroupsButton_2 = uibutton(app.GridLayout2, 'push');
            app.NamegroupsButton_2.ButtonPushedFcn = createCallbackFcn(app, @NamegroupsButton_2Pushed, true);
            app.NamegroupsButton_2.Text = 'Name groups';
            app.FinishButton = uibutton(app.GridLayout2, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = subapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end